classdef AdvancedPlotModule < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        AdvancedPlotModuleGUI     matlab.ui.Figure
        TSNETestButton            matlab.ui.control.Button
        NumClassesPanel           matlab.ui.container.Panel
        GridLayout3               matlab.ui.container.GridLayout
        TabGroup2                 matlab.ui.container.TabGroup
        VarSelectionTab           matlab.ui.container.Tab
        GridLayout8               matlab.ui.container.GridLayout
        Tree_2                    matlab.ui.container.CheckBoxTree
        ResultTab                 matlab.ui.container.Tab
        GridLayout9               matlab.ui.container.GridLayout
        ClassTree                 matlab.ui.container.CheckBoxTree
        SelectButton              matlab.ui.control.Button
        LogCheckBox               matlab.ui.control.CheckBox
        MaskClassButton           matlab.ui.control.Button
        sqrt_CheckBox             matlab.ui.control.CheckBox
        SelectAllButton_3         matlab.ui.control.Button
        DeselectAllButton_2       matlab.ui.control.Button
        Add2TableButton           matlab.ui.control.Button
        SortClassesDropDown       matlab.ui.control.DropDown
        SortClassesDropDownLabel  matlab.ui.control.Label
        ClassesSpinner            matlab.ui.control.Spinner
        ClassesSpinnerLabel       matlab.ui.control.Label
        MaxClassesSpinner         matlab.ui.control.Spinner
        MaxClassesSpinnerLabel    matlab.ui.control.Label
        MinClassesSpinner         matlab.ui.control.Spinner
        MinClassesSpinnerLabel    matlab.ui.control.Label
        CLR_CheckBox              matlab.ui.control.CheckBox
        Norm_CheckBox             matlab.ui.control.CheckBox
        RunClassTestButton        matlab.ui.control.Button
        IterationsSpinnerLabel    matlab.ui.control.Label
        IterationsSpinner         matlab.ui.control.Spinner
        RandomSeedSpinnerLabel    matlab.ui.control.Label
        RandomSeedSpinner         matlab.ui.control.Spinner
        UIAxes2                   matlab.ui.control.UIAxes
        UIAxes                    matlab.ui.control.UIAxes
        GridLayout16              matlab.ui.container.GridLayout
        MaskButton                matlab.ui.control.Button
        ResetMaskButton_2         matlab.ui.control.Button
        DeselectAllButton         matlab.ui.control.Button
        SelectAllButton           matlab.ui.control.Button
        Tree                      matlab.ui.container.CheckBoxTree
        TabGroup                  matlab.ui.container.TabGroup
        ResultsTab                matlab.ui.container.Tab
        GridLayout14              matlab.ui.container.GridLayout
        SearchButton              matlab.ui.control.Button
        SearchEditField           matlab.ui.control.EditField
        DeleteSpectraButton       matlab.ui.control.Button
        DeleteColumn              matlab.ui.control.Button
        ShowfulltableCheckBox     matlab.ui.control.CheckBox
        AddColFilt                matlab.ui.control.Button
        ResetColFilt              matlab.ui.control.Button
        ResultsTable              matlab.ui.control.Table
        DataSelectionTab          matlab.ui.container.Tab
        GridLayout4               matlab.ui.container.GridLayout
        HistogramFilterButton     matlab.ui.control.Button
        ExcludeEmptyCheckBox      matlab.ui.control.CheckBox
        UnmaskButton              matlab.ui.control.Button
        SelectAllButton_2         matlab.ui.control.Button
        ResetMaskButton           matlab.ui.control.Button
        MaskSelectionButton       matlab.ui.control.Button
        VariableDropDown          matlab.ui.control.DropDown
        MethodDropDown            matlab.ui.control.DropDown
        MethodDropDownLabel       matlab.ui.control.Label
        ClassTable                matlab.ui.control.Table
        PlotDataStorageTab        matlab.ui.container.Tab
        GridLayout5               matlab.ui.container.GridLayout
        PlotsqrtCheckBox          matlab.ui.control.CheckBox
        MarkerSizeEditField       matlab.ui.control.NumericEditField
        InvertColormapCheckBox    matlab.ui.control.CheckBox
        StoreClassMedianButton    matlab.ui.control.Button
        StoreClassMeanButton      matlab.ui.control.Button
        StoreDataButton           matlab.ui.control.Button
        ColormapPreviewCheckBox   matlab.ui.control.CheckBox
        Options_ColormapDropDown  matlab.ui.control.DropDown
        ColormapDropDownLabel     matlab.ui.control.Label
        cmap_table                matlab.ui.control.Table
        PlotCLRCheckBox           matlab.ui.control.CheckBox
        PlotLogCheckBox           matlab.ui.control.CheckBox
        PlotDataCheckBox          matlab.ui.control.CheckBox
        SymbolDropDown            matlab.ui.control.DropDown
        SymbolDropDownLabel       matlab.ui.control.Label
        PlotSectionTab            matlab.ui.container.Tab
        GridLayout7               matlab.ui.container.GridLayout
        ExportStyleButton         matlab.ui.control.Button
        ReadStyleButton           matlab.ui.control.Button
        AllSettingsCheckBox       matlab.ui.control.CheckBox
        TabGroup3                 matlab.ui.container.TabGroup
        MinPlotXFiguresTab        matlab.ui.container.Tab
        GridLayout17              matlab.ui.container.GridLayout
        PlotSelectedButton        matlab.ui.control.Button
        Tree2                     matlab.ui.container.CheckBoxTree
        ScatterPlotsTab           matlab.ui.container.Tab
        GridLayout10              matlab.ui.container.GridLayout
        PlotDataCheckBox_2        matlab.ui.control.CheckBox
        PlotLogCheckBox_2         matlab.ui.control.CheckBox
        PlotCLRCheckBox_2         matlab.ui.control.CheckBox
        LinkAxesCheckBox          matlab.ui.control.CheckBox
        ExcludeEmptyCheckBox_2    matlab.ui.control.CheckBox
        PlotsqrtCheckBox_2        matlab.ui.control.CheckBox
        NewScatterPlotButton      matlab.ui.control.Button
        TernPlotTab               matlab.ui.container.Tab
        GridLayout11              matlab.ui.container.GridLayout
        Norm_CheckBox_2           matlab.ui.control.CheckBox
        PlotCLRCheckBox_3         matlab.ui.control.CheckBox
        PlotsqrtCheckBox_3        matlab.ui.control.CheckBox
        MaxSubpltSpinner          matlab.ui.control.Spinner
        MaxSubpltSpinnerLabel     matlab.ui.control.Label
        PlotDataCheckBox_3        matlab.ui.control.CheckBox
        PlotLogCheckBox_3         matlab.ui.control.CheckBox
        NewTernPlotButton         matlab.ui.control.Button
        ColorPreviewCheckBox      matlab.ui.control.CheckBox
        ActiveSetSpinner          matlab.ui.control.Spinner
        ActiveSetSpinnerLabel     matlab.ui.control.Label
        movedownButton            matlab.ui.control.Button
        moveupButton              matlab.ui.control.Button
        ClearAllButton            matlab.ui.control.Button
        RemoveGroupButton         matlab.ui.control.Button
        Tree_MultiCor_Groups      matlab.ui.container.CheckBoxTree
        Tree_MultiCor_VarName     matlab.ui.container.CheckBoxTree
        MultiCor_table            matlab.ui.control.Table
        Full_TTab                 matlab.ui.container.Tab
        GridLayout18              matlab.ui.container.GridLayout
        ShowFull_TCheckBox        matlab.ui.control.CheckBox
        Full_T                    matlab.ui.control.Table
        ContextMenu               matlab.ui.container.ContextMenu
        SaveMenu                  matlab.ui.container.Menu
        LoadMenu                  matlab.ui.container.Menu
    end


    properties (Access = private)
        MinPlotX
        click_time
        % #

        ResultsData
        Data
        SelectedNodes_MainTree
        new_set=true

    end

    methods (Access = private)

        function ResetTree(app,Tree2Update)
            if not(exist('Tree2Update','var'))
                Tree2Update=app.Tree;
            end

            if ~isempty(Tree2Update.SelectedNodes)
                app.SelectedNodes_MainTree = Tree2Update.SelectedNodes.NodeData;
            end

            a = Tree2Update.Children;
            a.delete;
        end

        function UpdateTreeSampleMin(app,Tree2Update)
            if not(exist('tree2update','var'))
                Tree2Update=app.Tree;
            end
            %%% [2,i,j,k,l]
            N=Tree2Update.CheckedNodes;

            if not(isempty(N))

                selectedNodes_T={N.Text}';
                selectedNodes=cell2mat({N.NodeData}');

                T_C=cell2mat({Tree2Update.Children.NodeData}');
                T_C=Tree2Update.Children(T_C(ismember(T_C,unique(selectedNodes(:,1)))));
                N=[N;T_C];

                selectedNodes_T={N.Text}';
                selectedNodes=cell2mat({N.NodeData}');

                update_ts=true;
            else
                update_ts=false;
                selectedNodes=[];
            end

            ResetTree(app,Tree2Update)
            T_tree_update=app.Full_T.Data(app.Full_T.Data.Active==true,:);
            if not(isempty(T_tree_update))  %strcmp(app.MineralDropDown.Value,'MultiMineralData')

                SampleName=unique(T_tree_update.(char('SampleName')));
                if numel(SampleName) > 0
                    for h=1:numel(SampleName)
                        p0 = uitreenode(Tree2Update,'Text',string(SampleName{h}),'NodeData',[h,0,0]);

                        Mineral=unique(T_tree_update.Mineral(strcmp(T_tree_update.SampleName,SampleName{h})));
                        if numel(Mineral) > 0
                            for i = 1:numel(Mineral)
                                p = uitreenode(p0,'Text',string(Mineral{i}),'NodeData',[h,i,0]);
                                %   p.ContextMenu = ContextMenu_thx;


                                u_MNs=unique(T_tree_update.MineralCalcFunction(strcmp(T_tree_update.SampleName,SampleName{h})&strcmp(T_tree_update.Mineral,Mineral{i})));
                                if numel(u_MNs) > 0
                                    for j = 1:length(u_MNs)
                                        if any(not(ismissing(u_MNs{j})))
                                            p1 = uitreenode(p,'Text',string(u_MNs{j}),'NodeData',[h,i,j]);
                                            %   p1.ContextMenu = ContextMenu_thx;
                                        end
                                    end
                                end
                            end
                            %         end
                        end
                    end
                end

                if update_ts==true
                    N=[];
                    try
                        TreeNodes_new={Tree2Update.Children.Text};
                        nodes2check_P=Tree2Update.Children(contains(TreeNodes_new,selectedNodes_T(selectedNodes(:,2)==0)));
                        P_old=unique(selectedNodes(selectedNodes(:,2)==0,1),'row');
                        nodes2check_final= [];
                        for n=1:numel(nodes2check_P)
                            nodes2check_P_C=(selectedNodes_T(selectedNodes(:,2)>0&selectedNodes(:,1)==P_old(n)));
                            nodes2check_final=[nodes2check_final; nodes2check_P(n).Children(contains({nodes2check_P(n).Children.Text},nodes2check_P_C))  ];%#ok
                        end
                        Tree2Update.CheckedNodes=nodes2check_final;
                        expand(Tree2Update)
                    catch ME
                        DisplayErrorNG(app.MinPlotX,ME,'Tree Update Error 01')
                    end
                end

            end
        end

        function UpdateTreeMinSample(app,Tree2Update)
            if not(exist('tree2update','var'))
                Tree2Update=app.Tree;
            end
            %%% [2,i,j,k,l]
            N=Tree2Update.CheckedNodes;

            if not(isempty(N))

                selectedNodes_T={N.Text}';
                selectedNodes=cell2mat({N.NodeData}');

                T_C=cell2mat({Tree2Update.Children.NodeData}');
                T_C=Tree2Update.Children(T_C(ismember(T_C,unique(selectedNodes(:,1)))));
                N=[N;T_C];

                selectedNodes_T={N.Text}';
                selectedNodes=cell2mat({N.NodeData}');

                update_ts=true;
            else
                update_ts=false;
                selectedNodes=[];
            end

            ResetTree(app,Tree2Update)
            T_tree_update=app.Full_T.Data(app.Full_T.Data.Active==true,:);

            if not(isempty(T_tree_update))  %strcmp(app.MineralDropDown.Value,'MultiMineralData')

                Mineral=unique(T_tree_update.(char('Mineral')));
                if numel(Mineral) > 0
                    for h=1:numel(Mineral)
                        p0 = uitreenode(Tree2Update,'Text',string(Mineral{h}),'NodeData',[h,0,0]);

                        SampleName=unique(T_tree_update.SampleName(strcmp(T_tree_update.Mineral,Mineral{h})));
                        if numel(SampleName) > 0
                            for i = 1:numel(SampleName)
                                p = uitreenode(p0,'Text',string(SampleName{i}),'NodeData',[h,i,0]);
                                %   p.ContextMenu = ContextMenu_thx;


                                u_MNs=unique(T_tree_update.MineralCalcFunction(strcmp(T_tree_update.Mineral,Mineral{h})&strcmp(T_tree_update.SampleName,SampleName{i})));
                                if numel(u_MNs) > 0
                                    for j = 1:length(u_MNs)
                                        if any(not(ismissing(u_MNs{j})))
                                            p1 = uitreenode(p,'Text',string(u_MNs{j}),'NodeData',[h,i,j]);
                                            %   p1.ContextMenu = ContextMenu_thx;
                                        end
                                    end
                                end
                            end
                            %         end
                        end
                    end
                end

                if update_ts==true
                    N=[];
                    try
                        TreeNodes_new={Tree2Update.Children.Text};
                        nodes2check_P=Tree2Update.Children(contains(TreeNodes_new,selectedNodes_T(selectedNodes(:,2)==0)));
                        P_old=unique(selectedNodes(selectedNodes(:,2)==0,1),'row');
                        nodes2check_final= [];
                        for n=1:numel(nodes2check_P)
                            nodes2check_P_C=(selectedNodes_T(selectedNodes(:,2)>0&selectedNodes(:,1)==P_old(n)));
                            nodes2check_final=[nodes2check_final; nodes2check_P(n).Children(contains({nodes2check_P(n).Children.Text},nodes2check_P_C))  ];%#ok
                        end
                        Tree2Update.CheckedNodes=nodes2check_final;
                        expand(Tree2Update)
                    catch ME
                        DisplayErrorNG(app.MinPlotX,ME,'Tree Update Error 02')

                    end
                end

            end
        end


        function  update_results_table(app)
            checkedNodes = app.Tree.CheckedNodes;
            T=app.Full_T.Data(app.Full_T.Data.Active==true,:);

            if isfield(app.ResultsData,'ColumnFilter') && not(isempty(app.ResultsData.ColumnFilter))
                T=T(:,app.ResultsData.ColumnFilter);
            end

            if app.ShowfulltableCheckBox.Value==false
                T=T(:,ismember(T.Properties.VariableNames,{'ResId','Active','SpotId','SampleName','Point','Mineral','MineralCalcFunction','AnalysisName','Sample','AnalysisType'})); %ATH change opitons
            end


            if not(isempty(checkedNodes))
                NodeText={checkedNodes.Text}';
                NodeData=cell2mat({checkedNodes.NodeData}');
                Mineral=NodeText((NodeData(:,2)==0 & NodeData(:,3)==0) | contains(NodeText,'unknown'));
                MineralCalcFunction=NodeText(not(NodeData(:,2)==0 & NodeData(:,3)==0) & NodeData(:,3)>0);
                SampleName=NodeText(not(NodeData(:,2)==0 & NodeData(:,3)==0) & NodeData(:,3)==0);

                if not(isempty(Mineral))
                T=T(contains(T.Mineral,Mineral),:);
                end
                if not(isempty(SampleName))
                T=T(contains(T.SampleName,SampleName),:);
                end
                if not(isempty(MineralCalcFunction))
                T=T(contains(T.MineralCalcFunction,MineralCalcFunction),:);
                end
            end


            app.ResultsTable.Data=T;
            app.ResultsTable.ColumnName=T.Properties.VariableNames;
            %ath add if active tab data filter
            update_class_table(app)
        end


        function update_class_table(app)

            if app.ExcludeEmptyCheckBox.Value==true
                T_class_table_update=app.Full_T.Data(app.Full_T.Data.Active==true,:);
            else
                T_class_table_update=app.Full_T.Data;
            end
            value = app.MethodDropDown.Value;
            sort_option="stable";
            switch value
                case 'Unique String'
                    try
                        className=unique(T_class_table_update.(char(app.VariableDropDown.Value)),sort_option);
                    catch
                        thx=T_class_table_update.(char(app.VariableDropDown.Value));
                        [thx(cellfun(@isempty,T_class_table_update.(char(app.VariableDropDown.Value))))]={' '};
                        T_class_table_update.(char(app.VariableDropDown.Value))=thx;
                        className=unique(T_class_table_update.(char(app.VariableDropDown.Value)),sort_option);

                    end

                    Value=[1:numel(className)]';
                    try
                        if isequal(className,app.ClassTable.Data.Name)
                            Active= app.ClassTable.Data.Active;
                        else
                            Active=true(numel(unique(T_class_table_update.(char(app.VariableDropDown.Value)))),1);
                        end
                    catch
                        Active=true(numel(unique(T_class_table_update.(char(app.VariableDropDown.Value)))),1);
                    end
                    app.ClassTable.Visible=true;

                    where= string(T_class_table_update.(char(app.VariableDropDown.Value)));
                    nvalues=zeros(size(Value));

                    for n=1:numel(className)

                        nvalues(n)= sum(ismember(where(find(T_class_table_update.Active)),className(n)));
                    end

                case 'Numeric'
                    className=string(unique(T_class_table_update.(char(app.VariableDropDown.Value))));
                    Value=unique(T_class_table_update.(char(app.VariableDropDown.Value)));

                    if any(isnan(Value))
                        className(isnan(Value))=[];
                        Value(isnan(Value))=[];
                        disp('NaN values ignored')
                    end

                    Active=true(size(Value));

                    app.ClassTable.Visible=true;

                    nvalues=zeros(size(Value));
                    where= T_class_table_update.(char(app.VariableDropDown.Value));
                    for n=1:numel(Value)
                        nvalues(n)= sum(ismember(where(T_class_table_update.Active),Value(n)));
                    end


                case 'NumClasses'
                    app.NumClassesPanel.Visible=true;
                    app.VariableDropDown.Visible=false;
                    app.ClassTable.Visible=false;


                    try
                        mapnr=1;
                        ClassMapNr=find(app.Data.Classes(mapnr).n_classes==app.ClassesSpinner.Value);
                        if isempty(ClassMapNr)
                            app.MaxClassesSpinner.Value=app.ClassesSpinner.Value;
                            app.MinClassesSpinner.Value=2;
                            RunClassTestButtonPushed(app)
                            ClassMapNr=find(app.Data.Classes(mapnr).n_classes==app.ClassesSpinner.Value);
                        end
                        classNumber=app.Data.Classes(mapnr).ClassMap(ClassMapNr).classNumber;
                        className=app.Data.Classes(mapnr).ClassMap(ClassMapNr).ClassesNames;
                    catch
                        if not(isfield(app.Data,'Classes'))
                            return
                        end
                        app.MaxClassesSpinner.Value=app.ClassesSpinner.Value;
                        app.MinClassesSpinner.Value=2;
                        RunClassTestButtonPushed(app)
                        ClassMapNr=find(app.Data.Classes(mapnr).n_classes==app.ClassesSpinner.Value);
                        classNumber=app.Data.Classes(mapnr).ClassMap(ClassMapNr).classNumber;
                        className=app.Data.Classes(mapnr).ClassMap(ClassMapNr).ClassesNames;
                    end


                    Value=sort(unique(classNumber(not(isnan(classNumber)))));
                    nvalues=zeros(size(Value));

                    if not(isempty(app.ClassTable.Data))&& isequal(className,app.ClassTable.Data.Name)
                        Active= app.ClassTable.Data.Active;
                    else
                        Active=true(size(Value));

                    end


                    for n=1:numel(unique(classNumber(isfinite(classNumber))))
                        nvalues(n)=sum(classNumber(isfinite(classNumber))==n);
                    end

            end


            T=table(Active,className,Value,nvalues);
            T.Properties.VariableNames={'Active','Name','Value','n'};

            app.ClassTable.Data=T;
            app.ClassTable.ColumnName=T.Properties.VariableNames;
        end

        function update_cmap_table(app)
            if app.ExcludeEmptyCheckBox.Value==true
                T_cmap_table_update=app.Full_T.Data(app.Full_T.Data.Active==true,:);
            else
                T_cmap_table_update=app.Full_T.Data;
            end

            value = app.MethodDropDown.Value;
                        sort_option="stable";

            switch value
                case 'Unique String'
                    try
                        className=unique(T_cmap_table_update.(char(app.VariableDropDown.Value)),sort_option);
                    catch
                        thx=T_cmap_table_update.(char(app.VariableDropDown.Value));
                        thx(cellfun(@isempty,thx))={''};

                        idx=find(cellfun(@isempty,thx));

                        T_cmap_table_update(idx,char(app.VariableDropDown.Value))={' '};
                        T_cmap_table_update(idx,char(app.VariableDropDown.Value))={''};
                        className=unique(T_cmap_table_update.(char(app.VariableDropDown.Value)),sort_option);
                        disp('fixed empty numeric cell2 ''''')
                    end
                    className=className(app.ClassTable.Data.Active);
                    Value=[1:numel(className)]';
                    Active=true(numel(Value),1);

                    app.ClassTable.Visible=true;

                    MarkerFaceColor= PlotMap_UpdateColormap(app,sum(app.ClassTable.Data.Active));

                    where=          T_cmap_table_update.(char(app.VariableDropDown.Value));
                    classNumber=nan(size(where));
                    c=0;
                    for n=1:numel(className)
                        classNumber(ismember(where,className(n)))=  n;
                    end
                    nvalues=[];
                    try
                    for n=1:numel(unique(classNumber(isfinite(classNumber))))
                        nvalues(end+1)=sum(classNumber(isfinite(classNumber)&(T_cmap_table_update.Active))==n);%#ok
                    end
                    catch
                        n
                        
                    end


                case 'Numeric'
                    className=string(unique(T_cmap_table_update.(char(app.VariableDropDown.Value))));
                    className=className(app.ClassTable.Data.Active);

                    Value=unique(T_cmap_table_update.(char(app.VariableDropDown.Value)));
                    if any(isnan(Value))

                    end
                    Value=Value(app.ClassTable.Data.Active);

                    Active=true(numel(Value),1);

                    app.ClassTable.Visible=true;

                    MarkerFaceColor= PlotMap_UpdateColormap(app,sum(app.ClassTable.Data.Active));
                    where= T_cmap_table_update.(char(app.VariableDropDown.Value));

                    if iscell(where)
                        where=[where{:}]';
                    end

                    classNumber=nan(size(where));
                    for n=1:numel(Value)
                        classNumber(ismember(where,Value(n)))=  n;
                    end

                    nvalues=[];
                    for n=1:numel(unique(classNumber(isfinite(classNumber))))
                        nvalues(end+1)=sum(classNumber(isfinite(classNumber)&(T_cmap_table_update.Active))==n);%#ok
                    end


                case 'NumClasses'
                    app.NumClassesPanel.Visible=true;
                    app.VariableDropDown.Visible=false;
                    app.ClassTable.Visible=false;


                    try
                        mapnr=1;
                        ClassMapNr=find(app.Data.Classes(mapnr).n_classes==app.ClassesSpinner.Value);
                        if isstruct(app.Data.Classes) && isempty(ClassMapNr)
                            app.MaxClassesSpinner.Value=app.ClassesSpinner.Value;
                            app.MinClassesSpinner.Value=2;
                            RunClassTestButtonPushed(app)
                            ClassMapNr=find(app.Data.Classes(mapnr).n_classes==app.ClassesSpinner.Value);
                        end
                        classNumber=app.Data.Classes(mapnr).ClassMap(ClassMapNr).classNumber;
                        className=app.Data.Classes(mapnr).ClassMap(ClassMapNr).ClassesNames;
                    catch
                        if not(isfield(app.Data,'Classes'))
                            return
                        end
                        app.MaxClassesSpinner.Value=app.ClassesSpinner.Value;
                        app.MinClassesSpinner.Value=2;
                        RunClassTestButtonPushed(app)
                        ClassMapNr=find(app.Data.Classes(mapnr).n_classes==app.ClassesSpinner.Value);
                        classNumber=app.Data.Classes(mapnr).ClassMap(ClassMapNr).classNumber;
                        className=app.Data.Classes(mapnr).ClassMap(ClassMapNr).ClassesNames;
                    end

                    Active=true(numel(unique(classNumber(not(isnan(classNumber))))),1);

                    Value=sort(unique(classNumber(not(isnan(classNumber)))));


                    MarkerFaceColor= PlotMap_UpdateColormap(app,numel(Active));


                    nvalues=[];
                    for n=1:numel(unique(classNumber(isfinite(classNumber))))%ath here mask
                        nvalues(end+1)=sum(classNumber(isfinite(classNumber)&(T_cmap_table_update.Active))==n);%#ok
                    end
            end


            T=table(Active,className,Value,MarkerFaceColor,nvalues');
            T.Properties.VariableNames={'Active','Name','Value','MarkerFaceColor','n'};


            %T=table(Active,className,Value,cmap);
            %T.Properties.VariableNames={'Active','Name','Marker','SymbolSize','MarkerFaceColor'};

            %  switch markerdropdown
            % case 'custom'
            %   if size(app.cmap_table.Data.Symbol,1)==num_C
            %                           Marker=app.cmap_table.Data.Marker;
            %                           SymbolSize=app.cmap_table.Data.SymbolSize;
            %   else
            %Marker=markerdropdown;
            %SymbolSize=SymbolSize_number;
            %   end
            % otherwise
            %Marker=markerdropdown;
            %SymbolSize=SymbolSize_number;


            app.cmap_table.Data=T;
            if app.ColormapPreviewCheckBox.Value==true
                for n=1:numel(Active)
                    s = uistyle('BackgroundColor',MarkerFaceColor(n,:));
                    %addStyle(app.cmap_table,s,'row',n)
                    addStyle(app.cmap_table,s,'cell',[n,4])


                end
                % s = uistyle('BackgroundColor',[1 1 1 ]);
                %   addStyle(app.cmap_table,s,'column',[1:3 5])
            else
                s = uistyle('BackgroundColor',[1 1 1 ]);

                addStyle(app.cmap_table,s,'column',1:size(app.cmap_table.Data,2))
            end
        end

        function [ColorMap] = PlotMap_UpdateColormap(app,Resolution,~)
            if (isequal(app.Options_ColormapDropDown.Value,123)||isequal(app.Options_ColormapDropDown.Value,[123 123 123])) && (Resolution==4||Resolution==3)
                if Resolution==4
                    ColorMap=[
                        0.65098      0.65098      0.65098
                        1      0.41176      0.16078
                        0.39216      0.83137      0.07451
                        0.07451      0.62353            1];
                elseif  Resolution==3
                    ColorMap=[
                        1      0.41176      0.16078
                        0.39216      0.83137      0.07451
                        0.07451      0.62353            1];
                end

            elseif  isfield(app.Data,'CustomColorMap') && isequal(app.Options_ColormapDropDown.Value,app.Data.CustomColorMap) && size(app.Data.CustomColorMap,1)==Resolution
                ColorMap=app.Data.CustomColorMap;
            else
                %  SelColorMap = find(cellfun(@(x) isequal(x, app.Options_ColormapDropDown.Value), app.Options_ColormapDropDown.ItemsData))

                if app.InvertColormapCheckBox.Value
                    ColorData =app.Options_ColormapDropDown.Value;
                else
                    ColorData =flip(app.Options_ColormapDropDown.Value);
                end

                if Resolution==1
                    ColorMap = [0,0,0];
                else
                    Step = (Resolution-1)/(size(ColorData,1)-1);
                    X = 1:Step:Resolution;


                    ColorMap = zeros(length(1:Resolution),size(ColorData,2));
                    for i = 1:size(ColorData,2)
                        ColorMap(:,i) = interp1(X',ColorData(:,i),1:Resolution);
                    end

                    % upper & lower
                end
            end
        end


        function update_MultiCor_table(app,selected_MultiCor)
            checkedNodes=app.Tree_MultiCor_Groups.CheckedNodes;

            if not(isempty(checkedNodes))
                checkedNodes= app.Tree_MultiCor_Groups.CheckedNodes(cellfun(@isnumeric,{app.Tree_MultiCor_Groups.CheckedNodes.NodeData}));
                nd=reshape([checkedNodes.NodeData],2,[])';
                nodes={app.Tree_MultiCor_Groups.Children(:).Children};
                nodes=cat(1,nodes{:});
                nd_all=reshape([nodes.NodeData],2,[])';
                %
                %                 for n=1:size(unique(nd_all(:,1)))
                test= ismember(nd_all,nd,'rows');
            end

            T=table();
            if isstruct(app.Data.MultiCorPlot) && not(isempty(app.Data.MultiCorPlot)) && not(isempty(fieldnames(app.Data.MultiCorPlot)))
                %  app.ActiveSetSpinner.Visible=true;
                % app.ActiveSetSpinnerLabel.Visible=true;
                if numel(app.Data.MultiCorPlot)<selected_MultiCor
                    selected_MultiCor=numel(app.Data.MultiCorPlot);
                    app.ActiveSetSpinner.Value=selected_MultiCor;
                    disp('Error selected_MultiCor outside of numel(app.Data.MultiCorPlot)')
                end
                for selected_subMultiCor=1:numel(app.Data.MultiCorPlot(selected_MultiCor).ClassNames)
                    MarkerFaceColor=app.Data.MultiCorPlot(selected_MultiCor).MarkerFaceColor{selected_subMultiCor};
                    Symbol=app.Data.MultiCorPlot(selected_MultiCor).Symbol{selected_subMultiCor};
                    Name=app.Data.MultiCorPlot(selected_MultiCor).ClassNames{selected_subMultiCor};
                    SymbolSize=app.Data.MultiCorPlot(selected_MultiCor).SymbolSize{selected_subMultiCor};
                    Active= app.Data.MultiCorPlot(selected_MultiCor).Active{selected_subMultiCor};
                    Group=repmat(['Group_' num2str(selected_subMultiCor)],size(Active,1),1);
                    Group=string(Group);
                    classNumber=app.Data.MultiCorPlot(selected_MultiCor).classNumber{selected_subMultiCor};
                    nvalues=[];
                    for n=1:numel(unique(classNumber(isfinite(classNumber))))
                        test4nan=sum(isnan(app.Data.MultiCorPlot(selected_MultiCor).DataMat{selected_subMultiCor}),2); %ath here change to mask and store mask insead of nan
                        test4nan=test4nan(isfinite(classNumber))==size(app.Data.MultiCorPlot(selected_MultiCor).DataMat{selected_subMultiCor},2);
                        nvalues(end+1)=sum(classNumber(isfinite(classNumber)& not(test4nan))==n);%#ok
                    end
                    try
                        if app.AllSettingsCheckBox.Value==true
                            MarkerEdgeColor=app.Data.MultiCorPlot(selected_MultiCor).MarkerEdgeColor{selected_subMultiCor};
                            MarkerEdgeAlpha=app.Data.MultiCorPlot(selected_MultiCor).MarkerEdgeAlpha{selected_subMultiCor};
                            MarkerFaceAlpha=app.Data.MultiCorPlot(selected_MultiCor).MarkerFaceAlpha{selected_subMultiCor};

                            LineWidth=app.Data.MultiCorPlot(selected_MultiCor).LineWidth{selected_subMultiCor};


                            T=[T;table(Active,Group,Name,Symbol,SymbolSize,MarkerFaceColor,MarkerFaceAlpha,nvalues',MarkerEdgeColor,MarkerEdgeAlpha,LineWidth)];%here

                        else
                            T=[T;table(Active,Group,Name,Symbol,SymbolSize,MarkerFaceColor,nvalues')];%here
                        end

                    catch ME
                        DisplayErrorNG(app.MinPlotX,ME,'Error in MultiCor_table update')
                    end

                end


                if app.AllSettingsCheckBox.Value==true
                    T.Properties.VariableNames={'Active';'Group'; 'Name'; 'Symbol'; 'SymbolSize';'MarkerFaceColor';'MarkerFaceAlpha';'n';'MarkerEdgeColor';'MarkerEdgeAlpha';'LineWidth'};
                    c_editable=[true,false,true,true,true,false,true,false,false,true,true];
                else
                    T.Properties.VariableNames={'Active';'Group'; 'Name'; 'Symbol'; 'SymbolSize';'MarkerFaceColor';'n'};
                    c_editable=[true,false,true,true,true,false,false];

                end
            else
                app.ActiveSetSpinner.Visible=false;
                app.ActiveSetSpinnerLabel.Visible=false;
                app.ActiveSetSpinner.Value=0;
                c_editable=[true,false,true,true,true,false,false];

            end


            % T=table(Active,group,className,Symbol',SymbolSize,cmap);
            if exist('test','var') && not(isempty(T))
                T=T(test,:);

            end

            if app.ExcludeEmptyCheckBox.Value==true && not(isempty(T))
                T=T(not(T.(size(T,2)))==0,:);
            end
            app.MultiCor_table.Data=T;
            app.MultiCor_table.ColumnName=T.Properties.VariableNames;
            app.MultiCor_table.ColumnEditable=c_editable;

            if app.ColorPreviewCheckBox.Value==true && not(isempty(T))
                for n=1:numel(app.MultiCor_table.Data.Active)
                    s = uistyle('BackgroundColor',app.MultiCor_table.Data.MarkerFaceColor(n,:));
                    addStyle(app.MultiCor_table,s,'cell',[n,6])
                end
                %                 s = uistyle('BackgroundColor',[1 1 1 ]);
                %                 addStyle(app.MultiCor_table,s,'column',[1:5 7])

                if app.AllSettingsCheckBox.Value==true
                    for n=1:numel(app.MultiCor_table.Data.Active)
                        s = uistyle('BackgroundColor',app.MultiCor_table.Data.MarkerEdgeColor(n,:));
                        addStyle(app.MultiCor_table,s,'cell',[n,9])
                    end
                end
            else
                s = uistyle('BackgroundColor',[1 1 1 ]);

                addStyle(app.MultiCor_table,s,'column',1:size(app.MultiCor_table.Data,2))
            end


        end

        function update_MultiCor_trees(app,whichtree)
            switch whichtree
                case 'Varname'
                    ResetTree(app,app.Tree_MultiCor_VarName)
                    if isstruct(app.Data.MultiCorPlot)&& not(isempty(app.Data.MultiCorPlot)) && not(isempty(fieldnames(app.Data.MultiCorPlot)))
                        for n=1:numel(app.Data.MultiCorPlot)
                            p=  uitreenode(app.Tree_MultiCor_VarName,"Text",['Set_' num2str(n)],'NodeData',app.Data.MultiCorPlot(n));
                            for m=1:numel(app.Data.MultiCorPlot(n).VarNames)
                                uitreenode(p,"Text",app.Data.MultiCorPlot(n).VarNames{m},'NodeData',[n m]);
                            end

                        end
                    end
                case 'Groups'
                    selected_MultiCor=app.ActiveSetSpinner.Value;
                    CheckedNodes=app.Tree_MultiCor_Groups.CheckedNodes;
                    if not(isempty(CheckedNodes))
                        CheckedNodes= app.Tree_MultiCor_Groups.CheckedNodes(cellfun(@isnumeric,{app.Tree_MultiCor_Groups.CheckedNodes.NodeData}));

                        nodes={app.Tree_MultiCor_Groups.Children(:).Children};
                        nodes=cat(1,nodes{:});
                        nd_all=reshape([nodes.NodeData],2,[])';
                        nd_CheckedNodes=reshape([CheckedNodes.NodeData],2,[])';
                    end
                    ResetTree(app,app.Tree_MultiCor_Groups)

                    if isfield(app.Data,'MultiCorPlot') && isstruct(app.Data.MultiCorPlot)  && not(isempty(app.Data.MultiCorPlot)) && not(isempty(fieldnames(app.Data.MultiCorPlot)))

                        for n=1:numel(app.Data.MultiCorPlot(selected_MultiCor).ClassNames)
                            p=  uitreenode(app.Tree_MultiCor_Groups,"Text",['Group_' num2str(n)],'NodeData',app.Data.MultiCorPlot(selected_MultiCor));
                            for m=1:numel(app.Data.MultiCorPlot(selected_MultiCor).ClassNames{n})
                                uitreenode(p,"Text",app.Data.MultiCorPlot(selected_MultiCor).ClassNames{n}{m},'NodeData',[n m]);
                            end
                        end


                        nodes={app.Tree_MultiCor_Groups.Children(:).Children};
                        nodes=cat(1,nodes{:});
                        nd_all_new=reshape([nodes.NodeData],2,[])';

                        if not(isempty(CheckedNodes))

                            if isequal(nd_all,nd_all_new)
                                groups=unique(nd_CheckedNodes(:,1));
                                nodes2check=[];
                                for n=1:numel(groups)
                                    group=groups(n);
                                    nodes2check=[nodes2check; app.Tree_MultiCor_Groups.Children(group).Children(nd_CheckedNodes(nd_CheckedNodes(:,1)==group,2))];
                                end
                                app.Tree_MultiCor_Groups.CheckedNodes=nodes2check;

                            end
                        end
                    end
                case 0
                    update_MultiCor_trees(app,'Varname')
                    update_MultiCor_trees(app,'Groups')
            end
        end

        function store_data2plot(app,type)
            app.ColorPreviewCheckBox.Value=false;
            update_cmap_table(app)

            isnum = varfun(@isnumeric,app.Full_T.Data,'output','uniform');
            T_filtered=app.Full_T.Data(:,isnum);

            %  Active(checked nodes)=true
            % DataIn(mask,:)=nan
            T_filtered(app.Full_T.Data.Active==false,:)={nan};

            if isfield(app.ResultsData,'ColumnFilter') && not(isempty(app.ResultsData.ColumnFilter))
                T_filtered=T_filtered(:,app.ResultsData.ColumnFilter);
            end


            DataIn=table2array(T_filtered);
            VarNames=T_filtered.Properties.VariableNames;

            where=          app.Full_T.Data.(char(app.VariableDropDown.Value));
            classNumber=nan(size(where));
            c=0;
            for n=1:numel(app.cmap_table.Data.Name)
                if app.cmap_table.Data.Active(n)==true
                    c=c+1;
                    classNumber(ismember(where,app.cmap_table.Data.Name(n)))=  c;
                end
            end
            DataIn=DataIn(ismember(where,app.cmap_table.Data.Name(app.cmap_table.Data.Active)),:);
            classNumber=classNumber(ismember(where,app.cmap_table.Data.Name(app.cmap_table.Data.Active)),:);
            cmap= app.cmap_table.Data.MarkerFaceColor(app.cmap_table.Data.Active,:);


            className=app.cmap_table.Data.Name(app.cmap_table.Data.Active);


            Symbol(1:size(cmap,1),1)={app.SymbolDropDown.Value};
            SymbolSize(1:size(cmap,1),1)=app.MarkerSizeEditField.Value;
            Active(1:size(cmap,1),1)=true;

            if strcmp(type,'median')
                classNumber_u=unique(classNumber);
                DataIn_m=nan(numel(classNumber_u),size(DataIn,2));
                for n=1:numel(classNumber_u)
                    DataIn_m(n,:)=  median(DataIn(classNumber==n,:),1,'omitnan');

                    if numel(DataIn(classNumber==n,:)) == sum(isnan(DataIn(classNumber==n,:)))
                        DataIn_m(n,:)=nan;
                    end
                end
                DataIn_m(:,not(any(not(isnan(DataIn)))))   =nan;

                DataIn=DataIn_m;
                classNumber=classNumber_u;

                className_2=join([className repmat({' Median'},size(className))]);
            elseif strcmp(type,'mean')
                classNumber_u=unique(classNumber);
                DataIn_m=nan(numel(classNumber_u),size(DataIn,2));
                for n=1:numel(classNumber_u)
                    DataIn_m(n,:)=  mean(DataIn(classNumber==n,:),1,'omitnan');

                    if numel(DataIn(classNumber==n,:)) == sum(isnan(DataIn(classNumber==n,:)))
                        DataIn_m(n,:)=nan;
                    end
                    %   DataIn_m(n,:)=  median(DataIn(classNumber==n,:),1,'omitnan');

                end
                DataIn_m(:,not(any(not(isnan(DataIn)))))   =nan;

                DataIn=DataIn_m;
                classNumber=classNumber_u;

                className_2=join([className repmat({' Mean'},size(className))]);
            else
                className_2=className;
            end

            if app.new_set==true  || numel(app.Data.MultiCorPlot)==0 || isempty(fieldnames(app.Data.MultiCorPlot))
                if  isempty((app.Data.MultiCorPlot)) || isempty(fieldnames(app.Data.MultiCorPlot))
                    app.ActiveSetSpinner.Value=1;
                else
                    app.ActiveSetSpinner.Value=numel(app.Data.MultiCorPlot)+1;
                end
                app.new_set=false;

            else
                app.ActiveSetSpinner.Value=numel(app.Data.MultiCorPlot);
            end


            MarkerEdgeColor(1:size(cmap,1),1:3)=repmat([0 0 0],size(cmap,1),1);
            MarkerEdgeAlpha(1:size(cmap,1),1)=4/8;
            MarkerFaceAlpha(1:size(cmap,1),1)=3/8;

            LineWidth(1:size(cmap,1),1)=0.5;


            if   isfield(app.Data,'MultiCorPlot') && numel(app.Data.MultiCorPlot)>=app.ActiveSetSpinner.Value && isfield(app.Data.MultiCorPlot(app.ActiveSetSpinner.Value),'VarNames') && not(isempty(app.Data.MultiCorPlot(app.ActiveSetSpinner.Value).VarNames))&&isequal(app.Data.MultiCorPlot(app.ActiveSetSpinner.Value).VarNames,VarNames)

                app.Data.MultiCorPlot(app.ActiveSetSpinner.Value).DataMat{end+1}=DataIn;
                app.Data.MultiCorPlot(app.ActiveSetSpinner.Value).ResIds{end+1}=app.Full_T.Data.ResId(app.Full_T.Data.Active==true);
                app.Data.MultiCorPlot(app.ActiveSetSpinner.Value).type{end+1}=type;
                app.Data.MultiCorPlot(app.ActiveSetSpinner.Value).ClassCol{end+1}=char(app.VariableDropDown.Value);

                app.Data.MultiCorPlot(app.ActiveSetSpinner.Value).MarkerFaceColor{end+1}=cmap;
                app.Data.MultiCorPlot(app.ActiveSetSpinner.Value).Symbol{end+1}=Symbol;

                app.Data.MultiCorPlot(app.ActiveSetSpinner.Value).MarkerEdgeColor{end+1}=MarkerEdgeColor;
                app.Data.MultiCorPlot(app.ActiveSetSpinner.Value).MarkerEdgeAlpha{end+1}=MarkerEdgeAlpha;
                app.Data.MultiCorPlot(app.ActiveSetSpinner.Value).MarkerFaceAlpha{end+1}=MarkerFaceAlpha;

                app.Data.MultiCorPlot(app.ActiveSetSpinner.Value).LineWidth{end+1}=LineWidth;


                app.Data.MultiCorPlot(app.ActiveSetSpinner.Value).ClassNames{end+1}=className_2;
                app.Data.MultiCorPlot(app.ActiveSetSpinner.Value).ClassNames_original{end+1}=className;


                app.Data.MultiCorPlot(app.ActiveSetSpinner.Value).classNumber{end+1}=classNumber;

                app.Data.MultiCorPlot(app.ActiveSetSpinner.Value).SymbolSize{end+1}=SymbolSize;
                app.Data.MultiCorPlot(app.ActiveSetSpinner.Value).Active{end+1}=Active;
                selected_MultiCor=app.ActiveSetSpinner.Value;

            else

                app.Data.MultiCorPlot(app.ActiveSetSpinner.Value).VarNames=VarNames;
                app.Data.MultiCorPlot(app.ActiveSetSpinner.Value).DataMat{1}=DataIn;
                app.Data.MultiCorPlot(app.ActiveSetSpinner.Value).ResIds{1}=app.Full_T.Data.ResId(app.Full_T.Data.Active==true);
                app.Data.MultiCorPlot(app.ActiveSetSpinner.Value).type{1}=type;
                app.Data.MultiCorPlot(app.ActiveSetSpinner.Value).ClassCol{1}=char(app.VariableDropDown.Value);

                app.Data.MultiCorPlot(app.ActiveSetSpinner.Value).MarkerFaceColor{1}=cmap;
                app.Data.MultiCorPlot(app.ActiveSetSpinner.Value).Symbol{1}=Symbol;

                app.Data.MultiCorPlot(app.ActiveSetSpinner.Value).MarkerEdgeColor{1}=MarkerEdgeColor;
                app.Data.MultiCorPlot(app.ActiveSetSpinner.Value).MarkerEdgeAlpha{1}=MarkerEdgeAlpha;
                app.Data.MultiCorPlot(app.ActiveSetSpinner.Value).MarkerFaceAlpha{1}=MarkerFaceAlpha;

                app.Data.MultiCorPlot(app.ActiveSetSpinner.Value).LineWidth{1}=LineWidth;


                app.Data.MultiCorPlot(app.ActiveSetSpinner.Value).ClassNames{1}=className_2;
                app.Data.MultiCorPlot(app.ActiveSetSpinner.Value).ClassNames_original{1}=className;

                app.Data.MultiCorPlot(app.ActiveSetSpinner.Value).classNumber{1}=classNumber;
                app.Data.MultiCorPlot(app.ActiveSetSpinner.Value).SymbolSize{1}=SymbolSize;
                app.Data.MultiCorPlot(app.ActiveSetSpinner.Value).Active{1}=Active;
                selected_MultiCor=app.ActiveSetSpinner.Value;
            end


            update_MultiCor_trees(app,0)
            update_MultiCor_table(app,selected_MultiCor)

            Minerals=unique(app.Full_T.Data.Mineral(app.Full_T.Data.Active==true,:));
            update_plot_selection_tree(app,Minerals)

        end

        function update_plot_selection_tree(app,Minerals)
            ResetTree(app,app.Tree2);
            if not(exist('Minerals','var'))
                checkedNodes = app.Tree.CheckedNodes;

                Minerals=unique(app.Full_T.Data.Mineral(app.Full_T.Data.Active==true,:));
                if not(isempty(checkedNodes))
                    NodeText={checkedNodes.Text}';
                    NodeData=cell2mat({checkedNodes.NodeData}');
                    Mineral_nodes=NodeText((NodeData(:,2)==0 & NodeData(:,3)==0) | contains(NodeText,'unknown'));
                    Minerals=Mineral_nodes(contains(Mineral_nodes,Minerals));
                end
            end

            [~,options_definition]=plot_tern_fun([],[]);

            [~,options_definition_XY]=plot_XY_fun([],[]);

            [~,options_definition_multi_XY]=plot_multi_XY_fun([],[]);

            [~,options_definition_binay]=plot_binary_fun([],[]);

            p0 = uitreenode(app.Tree2,'Text',"Stacked Binary Plots",'NodeData','Stacked_Binary');

            for n=1:numel(Minerals)

                p0 = uitreenode(app.Tree2,'Text',string(Minerals{n}),'NodeData',Minerals{n});


                plotoptions_multi_XY=options_definition_multi_XY.type.options(contains(options_definition_multi_XY.type.description,CheckMinName(app.MinPlotX,Minerals{n})));
                plotoptions_multi_XY_description=options_definition_multi_XY.type.description(contains(options_definition_multi_XY.type.description,CheckMinName(app.MinPlotX,Minerals{n})));

                plotoptions_XY=options_definition_XY.type.options(contains(options_definition_XY.type.description,CheckMinName(app.MinPlotX,Minerals{n})));
                plotoptions_XY_description=options_definition_XY.type.description(contains(options_definition_XY.type.description,CheckMinName(app.MinPlotX,Minerals{n})));

                plotoptions=options_definition.type.options(contains(options_definition.type.description,CheckMinName(app.MinPlotX,Minerals{n})));
                plotoptions_description=options_definition.type.description(contains(options_definition.type.description,CheckMinName(app.MinPlotX,Minerals{n})));

                plotoptions_binary=options_definition_binay.type.options(contains(options_definition_binay.type.description,CheckMinName(app.MinPlotX,Minerals{n})));
                plotoptions_binary_description=options_definition_binay.type.description(contains(options_definition_binay.type.description,CheckMinName(app.MinPlotX,Minerals{n})));

                if numel(plotoptions)>0
                    p1 = uitreenode(p0,'Text',"Ternary",'NodeData','');
                    for t=1:numel(plotoptions)
                        p2 = uitreenode(p1,'Text',string(plotoptions_description{t}),'NodeData',string(plotoptions{t}));
                    end
                end
                if numel(plotoptions_XY)>0
                    p1 = uitreenode(p0,'Text',"XY Plot",'NodeData','');

                    for t=1:numel(plotoptions_XY)
                        p2 = uitreenode(p1,'Text',string(plotoptions_XY_description{t}),'NodeData',string(plotoptions_XY{t}));
                    end
                end

                if numel(plotoptions_multi_XY)>0
                    p1 = uitreenode(p0,'Text',"Multi XY Plot",'NodeData','');

                    for t=1:numel(plotoptions_multi_XY)
                        p2 = uitreenode(p1,'Text',string(plotoptions_multi_XY_description{t}),'NodeData',string(plotoptions_multi_XY{t}));
                    end
                end

                if numel(plotoptions_binary)>0
                    p1 = uitreenode(p0,'Text',"Binary Plot",'NodeData','');

                    for t=1:numel(plotoptions_binary)
                        p2 = uitreenode(p1,'Text',string(plotoptions_binary_description{t}),'NodeData',string(plotoptions_binary{t}));
                    end
                end
            end

%             thx=app.Tree2.Children((strcmp({app.Tree2.Children.Text},'unknown')| cellfun(@numel,{(app.Tree2.Children.Children)})==0 )& not(strcmp({app.Tree2.Children.Text},'Stacked Binary Plots')));
%            thx.delete;
        end


    end

    methods (Access = public)

        function update_1_public(app,state)
            switch state
                case 'filter'
                    update_class_table(app)

                    update_results_table(app)
                    UpdateTreeMinSample(app,app.Tree)

                    update_cmap_table(app)

                case 'reset'
                    ResetMaskButtonPushed(app)
            end
        end

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            try
                            movegui(app.AdvancedPlotModuleGUI, 'center');
                app.MinPlotX=mainapp;
                app.Data.MultiCorPlot=struct();

                %% ColormapDropDown
                if isdeployed
                    cmap_struct=ReadColorMaps(fullfile(app.MinPlotX.installpath,'Dev','XMT','XMap_ColorMaps.txt'));
                else
                    cmap_struct=ReadColorMaps(which(fullfile('Dev','XMT','XMap_ColorMaps.txt')));
                end

                app.Options_ColormapDropDown.Items={cmap_struct.Name};
                app.Options_ColormapDropDown.ItemsData={cmap_struct.Code};

                %% get compiled table from MinPlotX
                T_cell={};
                for n=1:numel(app.MinPlotX.MinPlotXData.SampleNames)
                    T=get_compiled_table(app.MinPlotX,n);
                    T_cell{end+1}=T; %#ok
                end
                T=tablevertcat_NG(app.MinPlotX,T_cell);
                ResId=(1:size(T,1))';
                Active=true(size(T,1),1);
                T_A=table(ResId,Active);

                if not(any(strcmp('Active',T.Properties.VariableNames)))
                    T =[T_A(:,2) T];
                end
                if not(any(strcmp('ResId',T.Properties.VariableNames)))
                    T =[T_A(:,1) T];
                end
                app.Full_T.Data=T;

                app.Full_T.Data.Active(isnan(app.Full_T.Data.Active))=false;

                app.Full_T.ColumnName =app.Full_T.Data.Properties.VariableNames;            %% update GUI

                UpdateTreeMinSample(app,app.Tree)
                MethodDropDownValueChanged(app)
                update_results_table(app)

            catch ME
                DisplayErrorNG(app.MinPlotX,ME,'AdvancedPlotModule Startup Error')
            end
        end

        % Button pushed function: DeleteSpectraButton
        function DeleteSpectraButtonPushed(app, event)
            if isempty(app.Data)
                return
            end

            %   if app.Data.Classes isnot emty warn
            app.Data.Classes=[];

            %ATH remove from classification results=[]
            selections = unique(app.ResultsTable.Selection(:,1));
            app.Full_T.Data(app.ResultsTable.Data.ResId(selections),:)=[];

            update_results_table(app)
            %update_MultiCor_trees(app,0)
        end

        % Button pushed function: DeleteColumn
        function DeleteColumnButtonPushed(app, event)
            if isempty(app.Data)
                return
            end
            selections = unique(app.ResultsTable.ColumnName(app.ResultsTable.Selection(:,2)));

            not2remove={'ResId','Active','SampleName','Mineral','MineralCalcFunction'}; %settings columns
            selections=selections(not(ismember(selections,not2remove)));

            for n=1:numel(selections)
                app.Full_T.Data(:,char(selections(n)))=[];
            end

            update_results_table(app)

        end

        % Value changed function: MethodDropDown
        function MethodDropDownValueChanged(app, event)
            value = app.MethodDropDown.Value;
            %  app.ColormapPreviewCheckBox.Value=false;

            value = app.MethodDropDown.Value;
            switch value
                case 'Unique String'
                    app.NumClassesPanel.Visible=false;
                    app.MaskSelectionButton.Visible=true;
                    app.ResetMaskButton.Visible=true;
                    app.UnmaskButton.Visible=true;
                    app.SelectAllButton_3.Visible=true;

                    if isempty(app.Full_T.Data)
                        app.VariableDropDown.Items={};
                        return
                    else
                        %  T_Thx=app.Full_T.Data(app.Full_T.Data.Active,:);
                        % T_Thx2=T_Thx(1,any(cellfun(@ischar,table2cell(T_Thx)))&not(sum(cellfun(@isempty,table2cell(T_Thx)))==size(T_Thx,1)));
                        %             app.VariableDropDown.Items=T_Thx2.Properties.VariableNames;
                        app.VariableDropDown.Items=app.Full_T.Data.Properties.VariableNames(any(cellfun(@ischar,table2cell(app.Full_T.Data))));
                        app.VariableDropDown.Visible=true;

                    end

                case 'Numeric'
                    app.NumClassesPanel.Visible=false;
                    app.MaskSelectionButton.Visible=true;
                    app.ResetMaskButton.Visible=true;
                    app.UnmaskButton.Visible=true;
                    app.SelectAllButton_3.Visible=true;

                    if isempty(app.Full_T.Data)
                        app.VariableDropDown.Items={};
                        return
                    else
                        app.VariableDropDown.Items=app.Full_T.Data.Active.VariableNames(any(cellfun(@isnumeric,table2cell(app.Full_T.Data))));
                        app.VariableDropDown.Visible=true;
                    end
                case 'NumClasses'
                    app.NumClassesPanel.Visible=true;
                    app.VariableDropDown.Visible=false;
                    app.MaskSelectionButton.Visible=false;
                    app.ResetMaskButton.Visible=true;
                    app.UnmaskButton.Visible=false;
                    app.SelectAllButton_3.Visible=false;

                    if isempty(app.Full_T.Data)
                        return
                    end
            end
            update_class_table(app)
            update_cmap_table(app)
        end

        % Button pushed function: MaskSelectionButton
        function MaskSelectionButtonPushed(app, event)

            %            old_mask= app.ClassTable.Data.Active;
            %                        ResetMaskButtonPushed(app)
            %                            app.ClassTable.Data.Active=old_mask;


            old_mask=app.Full_T.Data.Active;
            value = app.MethodDropDown.Value;
            if any(sum(app.ClassTable.Data.Active)==numel(app.ClassTable.Data.Active)) &&not(isempty(app.ClassTable.Selection))
                selection=unique(app.ClassTable.Selection(:,1));
            else
                selection=find(app.ClassTable.Data.Active);
            end
            switch value
                case 'Unique String'
                    %str2find=app.ClassTable.Data.Name(app.ClassTable.Data.Active);
                    where=          app.Full_T.Data.(char(app.VariableDropDown.Value));
                    classNumber=nan(size(where));
                    c=0;
                    for n=1:numel(app.ClassTable.Data.Name)
                        if app.ClassTable.Data.Active(n)==true
                            c=c+1;
                            classNumber(ismember(where,app.ClassTable.Data.Name(n)))=  c;
                        end
                    end
                    %                     set2nan=~ismember(where,app.ClassTable.Data.Name(app.ClassTable.Data.Active));
                    %                     classNumber(set2nan)=nan;
                    new_mask=ismember(where,app.ClassTable.Data.Name(selection));
                case 'Numeric'
                    where= app.Full_T.Data.(char(app.VariableDropDown.Value));
                    if iscell(where)
                        where=[where{:}]';
                    end


                    new_mask=ismember(where,app.ClassTable.Data.Value(selection));

                case 'NumClasses'
                    try
                        mapnr=1;
                        ClassMapNr=find(app.Data.Classes(mapnr).n_classes==app.ClassesSpinner.Value);
                        if isempty(ClassMapNr)
                            app.MaxClassesSpinner.Value=app.ClassesSpinner.Value;
                            app.MinClassesSpinner.Value=2;
                            RunClassTestButtonPushed(app)
                            ClassMapNr=find(app.Data.Classes(mapnr).n_classes==app.ClassesSpinner.Value);
                        end
                        classNumber=app.Data.Classes(mapnr).ClassMap(ClassMapNr).classNumber;
                    catch
                        disp('no classification')
                        [classNumber, ~, ~]= kmeans(DataIn,app.ClassesSpinner.Value, 'replicates',app.IterationsSpinner.Value);
                    end
                    new_mask=ismember(classNumber,app.ClassTable.Data.Value(selection));
            end


            %             app.Full_T.Data.Active(new_mask)=true;
            %             app.Full_T.Data.Active(new_mask)=true;

            app.Full_T.Data.Active(:)=(old_mask==true&new_mask==true);
            app.Full_T.Data.Active(:)=(old_mask==true&new_mask==true);


            ids =1:numel(app.ClassTable.Data.Active);
            app.ClassTable.Data.Active(selection)=true;
            app.ClassTable.Data.Active(not(ismember(ids,ids(selection))))=false;

            update_class_table(app)

            update_results_table(app)
            UpdateTreeMinSample(app,app.Tree)

            update_cmap_table(app)
        end

        % Button pushed function: UnmaskButton
        function UnmaskButtonPushed(app, event)
            old_mask=app.Full_T.Data.Active;

            value = app.MethodDropDown.Value;
            if any(sum(app.ClassTable.Data.Active)==numel(app.ClassTable.Data.Active)) &&not(isempty(app.ClassTable.Selection))
                selection=unique(app.ClassTable.Selection(:,1));
            else
                if not(isempty(unique(app.ClassTable.Selection(:,1))))
                    app.ClassTable.Data.Active(unique(app.ClassTable.Selection(:,1)))=true;
                end
                selection=app.ClassTable.Data.Active;
            end
            switch value
                case 'Unique String'
                    %str2find=app.ClassTable.Data.Name(app.ClassTable.Data.Active);
                    where=          app.Full_T.Data.(char(app.VariableDropDown.Value));
                    classNumber=nan(size(where));
                    c=0;
                    for n=1:numel(app.ClassTable.Data.Name)
                        if app.ClassTable.Data.Active(n)==true
                            c=c+1;
                            classNumber(ismember(where,app.ClassTable.Data.Name(n)))=  c;
                        end
                    end
                    %                     set2nan=~ismember(where,app.ClassTable.Data.Name(app.ClassTable.Data.Active));
                    %                     classNumber(set2nan)=nan;
                    new_mask=ismember(where,app.ClassTable.Data.Name(selection));
                case 'Numeric'
                    where= app.Full_T.Data.(char(app.VariableDropDown.Value));
                    if iscell(where)
                        where=[where{:}]';
                    end


                    new_mask=ismember(where,app.ClassTable.Data.Value(selection));

                case 'NumClasses'
                    try
                        mapnr=1;
                        ClassMapNr=find(app.Data.Classes(mapnr).n_classes==app.ClassesSpinner.Value);
                        if isempty(ClassMapNr)
                            app.MaxClassesSpinner.Value=app.ClassesSpinner.Value;
                            app.MinClassesSpinner.Value=2;
                            RunClassTestButtonPushed(app)
                            ClassMapNr=find(app.Data.Classes(mapnr).n_classes==app.ClassesSpinner.Value);
                        end
                        classNumber=app.Data.Classes(mapnr).ClassMap(ClassMapNr).classNumber;
                    catch
                        disp('no classification')
                        [classNumber, ~, ~]= kmeans(DataIn,app.ClassesSpinner.Value, 'replicates',app.IterationsSpinner.Value);
                    end
                    new_mask=ismember(classNumber,app.ClassTable.Data.Value(selection));
            end


            %  mask=old_mask==true & not(old_mask==false & new_mask==true);
            app.Full_T.Data.Active(new_mask)=true;
            app.Full_T.Data.Active(new_mask)=true;

            update_class_table(app)
            update_results_table(app)
            UpdateTreeMinSample(app,app.Tree)

            update_cmap_table(app)
        end

        % Button pushed function: ResetMaskButton
        function ResetMaskButtonPushed(app, event)

            if not(isempty(app.ClassTable.Data))
                app.ClassTable.Data.Active(:)=true;
            end
            app.Full_T.Data.Active(:)=true;
            app.Full_T.Data.Active(:)=true;
            update_class_table(app)
            update_results_table(app)
            UpdateTreeMinSample(app,app.Tree)

            update_cmap_table(app)

        end

        % Button pushed function: SelectAllButton_2
        function SelectAllButton_2Pushed(app, event)
            if any(not(app.ClassTable.Data.Active(:)))
                app.ClassTable.Data.Active(:)=(true);
            else
                app.ClassTable.Data.Active(:)=(false);
            end

            update_cmap_table(app)
        end

        % Value changed function: VariableDropDown
        function VariableDropDownValueChanged(app, event)
            %    app.ColormapPreviewCheckBox.Value=false;
            value = app.VariableDropDown.Value;
            update_class_table(app)
            update_cmap_table(app)
        end

        % Button pushed function: AddColFilt
        function AddColFiltButtonPushed(app, event)
            thx_table_Meta=app.Full_T.Data;
            list = thx_table_Meta.Properties.VariableNames; %ATH
            not2remove={'ResId','Active','SampleName','Mineral','MineralCalcFunction'}; %settings columns
            list2=list(not(ismember(list,not2remove)));
            [indx,~] = listdlg('ListString',list2,'SelectionMode','multi');
            figure(app.AdvancedPlotModuleGUI);
            app.ResultsData.ColumnFilter=find(ismember(list, [not2remove list2(indx)]));
            update_results_table(app)
        end

        % Button pushed function: ResetColFilt
        function ResetColFiltButtonPushed(app, event)
            app.ResultsData.ColumnFilter=[];
            update_results_table(app)
        end

        % Callback function: Tree
        function TreeCheckedNodesChanged(app, event)
            checkedNodes = app.Tree.CheckedNodes;
            update_results_table(app)
        end

        % Button pushed function: SelectAllButton
        function SelectAllButtonPushed(app, event)
            app.Tree.CheckedNodes=app.Tree.Children;
            update_results_table(app)

        end

        % Button pushed function: DeselectAllButton
        function DeselectAllButtonPushed(app, event)
            app.Tree.CheckedNodes=[];
            update_results_table(app)
        end

        % Value changed function: ShowfulltableCheckBox
        function ShowfulltableCheckBoxValueChanged(app, event)
            update_results_table(app)

        end

        % Value changed function: ExcludeEmptyCheckBox
        function ExcludeEmptyCheckBoxValueChanged(app, event)
            value = app.ExcludeEmptyCheckBox.Value;
            update_class_table(app)
            update_cmap_table(app)

            if app.ExcludeEmptyCheckBox.Value==true
                app.UnmaskButton.Visible=false;
            else
                app.UnmaskButton.Visible=true;
            end
        end

        % Button pushed function: TSNETestButton
        function TSNETestButtonPushed(app, event)
            %%

            meas=app.Data.MultiCorPlot(app.ActiveSetSpinner.Value).DataMat{1};
            species=app.Data.MultiCorPlot(app.ActiveSetSpinner.Value).ClassNames{1}(app.Data.MultiCorPlot(app.ActiveSetSpinner.Value).classNumber{1});

            meas(isnan(meas))=0;
            meas(meas<0)=0;

            figure


            try
                rng('default') % for reproducibility
                Y = tsne(meas,'Algorithm','exact','Distance','mahalanobis');
                subplot(2,2,1)
                gscatter(Y(:,1),Y(:,2),species)
                title('Mahalanobis')
            end
            try
                rng('default') % for fair comparison
                Y = tsne(meas,'Algorithm','exact','Distance','cosine');
                subplot(2,2,2)
                gscatter(Y(:,1),Y(:,2),species)
                title('Cosine')
                rng('default') % for fair comparison
            end

            try
                Y = tsne(meas,'Algorithm','exact','Distance','chebychev');
                subplot(2,2,3)
                gscatter(Y(:,1),Y(:,2),species)
                title('Chebychev')
            end
            try
                rng('default') % for fair comparison
                Y = tsne(meas,'Algorithm','exact','Distance','euclidean');
                subplot(2,2,4)
                gscatter(Y(:,1),Y(:,2),species)
                title('Euclidean')
            end
        end

        % Value changed function: ColormapPreviewCheckBox, 
        % ...and 1 other component
        function ColormapPreviewCheckBoxValueChanged(app, event)
            update_cmap_table(app)
        end

        % Value changed function: Options_ColormapDropDown
        function Options_ColormapDropDownValueChanged(app, event)
            update_cmap_table(app)
            app.cmap_table.Data.MarkerFaceColor(:,:)=PlotMap_UpdateColormap(app,numel(app.cmap_table.Data.Value));
        end

        % Value changed function: SymbolDropDown
        function SymbolDropDownValueChanged(app, event)
            value = app.SymbolDropDown.Value;
            if strcmp(value,'.')
                app.MarkerSizeEditField.Value=7.5;
            else
                app.MarkerSizeEditField.Value=10;
            end
        end

        % Button pushed function: StoreDataButton
        function StoreDataButtonPushed(app, event)
            store_data2plot(app,'normal')


        end

        % Button pushed function: StoreClassMeanButton
        function StoreClassMeanButtonPushed(app, event)
            store_data2plot(app,'mean')


        end

        % Button pushed function: StoreClassMedianButton
        function StoreClassMedianButtonPushed(app, event)
            store_data2plot(app,'median')

        end

        % Button pushed function: NewScatterPlotButton
        function NewScatterPlotButtonPushed(app, event)
            update_MultiCor_table(app,app.ActiveSetSpinner.Value)
            if isempty(app.Data.MultiCorPlot)
                return
            end

            checkedNodes_var = app.Tree_MultiCor_Groups.CheckedNodes;
            checkedNodes_groups = app.Tree_MultiCor_VarName.CheckedNodes;

            if isempty(checkedNodes_groups)
                message={'No Variables selected - Please Select two or more Variables from the variable set tree'};
                uialert(app.AdvancedPlotModuleGUI,message,'No Selection','Icon','Error');
                return
            elseif numel(checkedNodes_groups)>12
                selection = uiconfirm(app.AdvancedPlotModuleGUI, ...
                    "More than 12 Variables selected. This might take a long time are you sure you want to proceed?","Confirm Plot", ...
                    "Icon","warning");
                if strcmp(selection,'Cancel')
                    return
                end
            end


            DataIn=app.Data.MultiCorPlot;%(app.ActiveSetSpinner.Value);
            DataIn.link=app.LinkAxesCheckBox.Value;
            DataIn.ExcludeEmpty=app.ExcludeEmptyCheckBox_2.Value;
            DataIn.cmap=DataIn.MarkerFaceColor;


            if not(isempty(checkedNodes_groups))
                checkedNodes_groups = app.Tree_MultiCor_VarName.CheckedNodes(cellfun(@isnumeric,{app.Tree_MultiCor_VarName.CheckedNodes.NodeData}));

                nd=reshape([checkedNodes_groups.NodeData],2,[])';
                set_var=unique(nd(:,1),'stable');
                for n=1:numel(set_var)
                    subset_var=nd(nd(:,1)==set_var(n),2);
                    n_sg=1:numel(DataIn.VarNames);

                    not_subgroup=n_sg(not(ismember(n_sg,subset_var)));
                    %                      DataIn.DataMat{set_var}(:,not_subgroup)=[];
                    %                      DataIn.VarNames(not_subgroup)=[];
                    for k=1:numel(DataIn.DataMat)
                        DataIn.DataMat{k}(:,not_subgroup)=[];
                    end
                    DataIn.VarNames(not_subgroup)=[];

                end
            end

            if not(isempty(checkedNodes_var))

                checkedNodes_var = app.Tree_MultiCor_Groups.CheckedNodes(cellfun(@isnumeric,{app.Tree_MultiCor_Groups.CheckedNodes.NodeData}));

                nd=reshape([checkedNodes_var.NodeData],2,[])';

                nodes={app.Tree_MultiCor_Groups.Children(:).Children};
                nodes=cat(1,nodes{:});
                nd_all=reshape([nodes.NodeData],2,[])';

                for n=1:size(unique(nd_all(:,1),'stable'))
                    test= ismember(nd_all(nd_all(:,1)==n,:),nd,'rows');
                    % subset_var=test;

                    not_subgroup=find(not(test));


                    DataIn.Active{n}(not_subgroup)=[];%:
                    DataIn.MarkerFaceColor{n}(not_subgroup,:)=[];%:
                    DataIn.cmap{n}(not_subgroup,:)=[];%:
                    DataIn.Symbol{n}(not_subgroup)=[];%:
                    DataIn.ClassNames{n}(not_subgroup)=[];%:
                    DataIn.SymbolSize{n}(not_subgroup)=[];%:


                    DataIn.DataMat{n}(ismember(DataIn.classNumber{n},not_subgroup),:)=[];
                    DataIn.classNumber{n}(ismember(DataIn.classNumber{n},not_subgroup))=[];
                    % EdgeColor
                end

            end


            % %% test
            % meas=DataIn.DataMat{n};
            % species=DataIn.ClassNames{n}(DataIn.classNumber{n});
            %
            % meas(isnan(meas))=0;
            % meas(meas<0)=0;
            %
            % figure
            %
            %
            % try
            % rng('default') % for reproducibility
            % Y = tsne(meas,'Algorithm','exact','Distance','mahalanobis');
            % subplot(2,2,1)
            % gscatter(Y(:,1),Y(:,2),species)
            % title('Mahalanobis')
            % end
            % try
            % rng('default') % for fair comparison
            % Y = tsne(meas,'Algorithm','exact','Distance','cosine');
            % subplot(2,2,2)
            % gscatter(Y(:,1),Y(:,2),species)
            % title('Cosine')
            % rng('default') % for fair comparison
            % end
            %
            % try
            %     Y = tsne(meas,'Algorithm','exact','Distance','chebychev');
            % subplot(2,2,3)
            % gscatter(Y(:,1),Y(:,2),species)
            % title('Chebychev')
            % end
            % try
            % rng('default') % for fair comparison
            % Y = tsne(meas,'Algorithm','exact','Distance','euclidean');
            % subplot(2,2,4)
            % gscatter(Y(:,1),Y(:,2),species)
            % title('Euclidean')
            % end
            %
            % %%


            if  app.ExcludeEmptyCheckBox.Value==true

            end

            if app.PlotDataCheckBox_2.Value==true
                hfig=CorPlotFun(DataIn);
                CenterFig_fun(hfig);

            end

            if app.PlotsqrtCheckBox_2.Value==true
                DataIn_sqrt=DataIn;
                for n=1:numel(DataIn_sqrt.DataMat)
                    DataIn_sqrt.DataMat{n}=sqrt(DataIn_sqrt.DataMat{n});
                end
                hfig= CorPlotFun(DataIn_sqrt);
                CenterFig_fun(hfig);
                ts=sgtitle('sqrt');
                set(ts, 'Interpreter', 'none')
            end

            if app.PlotLogCheckBox_2.Value==true
                DataIn_log=DataIn;
                for n=1:numel(DataIn_log.DataMat)
                    DataIn_log.DataMat{n}=log(DataIn_log.DataMat{n});
                end
                hfig=CorPlotFun(DataIn_log);
          CenterFig_fun(hfig);
          

                ts=sgtitle('log');
                set(ts, 'Interpreter', 'none')
            end

            if app.PlotCLRCheckBox_2.Value==true
                DataIn_clr=DataIn;

                for n=1:numel(DataIn_clr.DataMat)
                    DataIn_clr.DataMat{n}=clr(DataIn_clr.DataMat{n});
                end
                hfig= CorPlotFun(DataIn_clr);
                CenterFig_fun(hfig)

                ts=sgtitle('clr');
                set(ts, 'Interpreter', 'none')

            end


        end

        % Callback function: Tree_MultiCor_Groups
        function Tree_MultiCor_GroupsCheckedNodesChanged(app, event)
            update_MultiCor_table(app,app.ActiveSetSpinner.Value)
        end

        % Button pushed function: moveupButton
        function moveupButtonPushed(app, event)
            if isempty(app.Tree_MultiCor_Groups.SelectedNodes)
                return
            end

            if cellfun(@isnumeric,{app.Tree_MultiCor_Groups.SelectedNodes.NodeData})
                n2move=[app.Tree_MultiCor_Groups.SelectedNodes.NodeData];

                app.Data.MultiCorPlot(app.ActiveSetSpinner.Value).DataMat{n2move(1)}(:,n2move(2))=[];
                if size(app.Data.MultiCorPlot(app.ActiveSetSpinner.Value).DataMat{n2move(1)},2)==0
                    app.Data.MultiCorPlot(app.ActiveSetSpinner.Value).DataMat(n2move(1))=[];
                end
                app.Data.MultiCorPlot(app.ActiveSetSpinner.Value).MarkerFaceColor{n2move(1)}(n2move(2),:)=[];
                app.Data.MultiCorPlot(app.ActiveSetSpinner.Value).Symbol{n2move(1)}(n2move(2))=[];
                app.Data.MultiCorPlot(app.ActiveSetSpinner.Value).SymbolSize{n2move(1)}(n2move(2))=[];
                app.Data.MultiCorPlot(app.ActiveSetSpinner.Value).MarkerEdgeColor{n2move(1)}(n2move(2))=[];
                app.Data.MultiCorPlot(app.ActiveSetSpinner.Value).MarkerEdgeAlpha{n2move(1)}(n2move(2))=[];
                app.Data.MultiCorPlot(app.ActiveSetSpinner.Value).MarkerFaceAlpha{n2move(1)}(n2move(2))=[];

                app.Data.MultiCorPlot(app.ActiveSetSpinner.Value).LineWidth{n2move(1)}(n2move(2))=[];


                app.Data.MultiCorPlot(app.ActiveSetSpinner.Value).Active{n2move(1)}(n2move(2))=[];
                app.Data.MultiCorPlot(app.ActiveSetSpinner.Value).ClassNames{n2move(1)}(n2move(2))=[];
                app.Data.MultiCorPlot(app.ActiveSetSpinner.Value).ClassNames_original{n2move(1)}(n2move(2))=[];

                app.Data.MultiCorPlot(app.ActiveSetSpinner.Value).ResIds{n2move(1)}(n2move(2))=[];
                app.Data.MultiCorPlot(app.ActiveSetSpinner.Value).type{n2move(1)}(n2move(2))=[];

                cn=app.Data.MultiCorPlot(app.ActiveSetSpinner.Value).classNumber{n2move(1)};

                cn(cn==n2move(2))=nan;
                cn(cn>n2move(2))=cn(cn>n2move(2))-1;
                app.Data.MultiCorPlot(app.ActiveSetSpinner.Value).classNumber{n2move(1)}=cn;


            else
                n2move =find(strcmp(app.Tree_MultiCor_Groups.SelectedNodes.Text,{app.Tree_MultiCor_Groups.Children.Text}));
                if n2move==1
                    return
                else
                    n_groups=1:numel(app.Data.MultiCorPlot(app.ActiveSetSpinner.Value).DataMat);
                    before=n_groups(not(n2move==n_groups)&n_groups<n2move-1);
                    after=n_groups(not(n2move==n_groups)&n_groups>n2move-2);

                    app.Data.MultiCorPlot(app.ActiveSetSpinner.Value).DataMat=app.Data.MultiCorPlot(app.ActiveSetSpinner.Value).DataMat([before n2move after]);
                    app.Data.MultiCorPlot(app.ActiveSetSpinner.Value).MarkerFaceColor=app.Data.MultiCorPlot(app.ActiveSetSpinner.Value).MarkerFaceColor([before n2move after]);
                    app.Data.MultiCorPlot(app.ActiveSetSpinner.Value).Symbol=app.Data.MultiCorPlot(app.ActiveSetSpinner.Value).Symbol([before n2move after]);

                    app.Data.MultiCorPlot(app.ActiveSetSpinner.Value).MarkerEdgeColor=app.Data.MultiCorPlot(app.ActiveSetSpinner.Value).MarkerEdgeColor([before n2move after]);
                    app.Data.MultiCorPlot(app.ActiveSetSpinner.Value).MarkerEdgeAlpha=app.Data.MultiCorPlot(app.ActiveSetSpinner.Value).MarkerEdgeAlpha([before n2move after]);
                    app.Data.MultiCorPlot(app.ActiveSetSpinner.Value).MarkerFaceAlpha=app.Data.MultiCorPlot(app.ActiveSetSpinner.Value).MarkerFaceAlpha([before n2move after]);

                    app.Data.MultiCorPlot(app.ActiveSetSpinner.Value).classNumber=app.Data.MultiCorPlot(app.ActiveSetSpinner.Value).classNumber([before n2move after]);
                    app.Data.MultiCorPlot(app.ActiveSetSpinner.Value).ClassNames=app.Data.MultiCorPlot(app.ActiveSetSpinner.Value).ClassNames([before n2move after]);
                    app.Data.MultiCorPlot(app.ActiveSetSpinner.Value).ClassNames_original=app.Data.MultiCorPlot(app.ActiveSetSpinner.Value).ClassNames_original([before n2move after]);
                    app.Data.MultiCorPlot(app.ActiveSetSpinner.Value).SymbolSize=app.Data.MultiCorPlot(app.ActiveSetSpinner.Value).SymbolSize([before n2move after]);
                    app.Data.MultiCorPlot(app.ActiveSetSpinner.Value).Active=app.Data.MultiCorPlot(app.ActiveSetSpinner.Value).Active([before n2move after]);

                    app.Data.MultiCorPlot(app.ActiveSetSpinner.Value).ResIds=app.Data.MultiCorPlot(app.ActiveSetSpinner.Value).ResIds([before n2move after]);
                    app.Data.MultiCorPlot(app.ActiveSetSpinner.Value).type=app.Data.MultiCorPlot(app.ActiveSetSpinner.Value).type([before n2move after]);

                end
                update_MultiCor_trees(app,'Groups')
                update_MultiCor_table(app,app.ActiveSetSpinner.Value)
            end
        end

        % Button pushed function: movedownButton
        function movedownButtonPushed(app, event)
            if isempty(app.Tree_MultiCor_Groups.SelectedNodes)
                return
            end

            if cellfun(@isnumeric,{app.Tree_MultiCor_Groups.SelectedNodes.NodeData})

            else
                n2move =find(strcmp(app.Tree_MultiCor_Groups.SelectedNodes.Text,{app.Tree_MultiCor_Groups.Children.Text}));
                if n2move==numel(app.Data.MultiCorPlot(app.ActiveSetSpinner.Value).DataMat)
                    return
                else
                    n_groups=1:numel(app.Data.MultiCorPlot(app.ActiveSetSpinner.Value).DataMat);
                    before=n_groups(not(n2move==n_groups)&n_groups<n2move+2);
                    after=n_groups(not(n2move==n_groups)&n_groups>n2move+1);

                    app.Data.MultiCorPlot(app.ActiveSetSpinner.Value).DataMat=app.Data.MultiCorPlot(app.ActiveSetSpinner.Value).DataMat([before n2move after]);
                    app.Data.MultiCorPlot(app.ActiveSetSpinner.Value).MarkerFaceColor=app.Data.MultiCorPlot(app.ActiveSetSpinner.Value).MarkerFaceColor([before n2move after]);
                    app.Data.MultiCorPlot(app.ActiveSetSpinner.Value).Symbol=app.Data.MultiCorPlot(app.ActiveSetSpinner.Value).Symbol([before n2move after]);


                    app.Data.MultiCorPlot(app.ActiveSetSpinner.Value).MarkerEdgeColor=app.Data.MultiCorPlot(app.ActiveSetSpinner.Value).MarkerEdgeColor([before n2move after]);
                    app.Data.MultiCorPlot(app.ActiveSetSpinner.Value).MarkerEdgeAlpha=app.Data.MultiCorPlot(app.ActiveSetSpinner.Value).MarkerEdgeAlpha([before n2move after]);
                    app.Data.MultiCorPlot(app.ActiveSetSpinner.Value).MarkerFaceAlpha=app.Data.MultiCorPlot(app.ActiveSetSpinner.Value).MarkerFaceAlpha([before n2move after]);

                    app.Data.MultiCorPlot(app.ActiveSetSpinner.Value).classNumber=app.Data.MultiCorPlot(app.ActiveSetSpinner.Value).classNumber([before n2move after]);
                    app.Data.MultiCorPlot(app.ActiveSetSpinner.Value).ClassNames=app.Data.MultiCorPlot(app.ActiveSetSpinner.Value).ClassNames([before n2move after]);
                    app.Data.MultiCorPlot(app.ActiveSetSpinner.Value).ClassNames_original=app.Data.MultiCorPlot(app.ActiveSetSpinner.Value).ClassNames_original([before n2move after]);
                    app.Data.MultiCorPlot(app.ActiveSetSpinner.Value).SymbolSize=app.Data.MultiCorPlot(app.ActiveSetSpinner.Value).SymbolSize([before n2move after]);
                    app.Data.MultiCorPlot(app.ActiveSetSpinner.Value).Active=app.Data.MultiCorPlot(app.ActiveSetSpinner.Value).Active([before n2move after]);

                    app.Data.MultiCorPlot(app.ActiveSetSpinner.Value).ResIds=app.Data.MultiCorPlot(app.ActiveSetSpinner.Value).ResIds([before n2move after]);
                    app.Data.MultiCorPlot(app.ActiveSetSpinner.Value).type=app.Data.MultiCorPlot(app.ActiveSetSpinner.Value).type([before n2move after]);


                end
                update_MultiCor_trees(app,'Groups')
                update_MultiCor_table(app,app.ActiveSetSpinner.Value)
            end
        end

        % Button pushed function: RemoveGroupButton
        function RemoveGroupButtonPushed(app, event)
            %app.group multi tree selected nodes n2delete=[NodeData(:,1)]
            % checkedNodes_groups = Nodesunique(reshape([app.Tree_MultiCor_VarName.SelectedNodes.NodeData],2,[])');
            if isempty(app.Tree_MultiCor_Groups.SelectedNodes)
                return
            end

            if cellfun(@isnumeric,{app.Tree_MultiCor_Groups.SelectedNodes.NodeData})
                n2delete=[app.Tree_MultiCor_Groups.SelectedNodes.NodeData];
                cn=app.Data.MultiCorPlot(app.ActiveSetSpinner.Value).classNumber{n2delete(1)};
                app.Data.MultiCorPlot(app.ActiveSetSpinner.Value).DataMat{n2delete(1)}(:,n2delete(2))=[];
                app.Data.MultiCorPlot(app.ActiveSetSpinner.Value).DataMat{n2delete(1)}(cn==n2delete(2),:)=[];

                cn(cn==n2delete(2))=[];
                cn(cn>n2delete(2))=cn(cn>n2delete(2))-1;
                app.Data.MultiCorPlot(app.ActiveSetSpinner.Value).classNumber{n2delete(1)}=cn;


                app.Data.MultiCorPlot(app.ActiveSetSpinner.Value).DataMat{n2delete(1)}(:,n2delete(2))=[];
                if size(app.Data.MultiCorPlot(app.ActiveSetSpinner.Value).DataMat{n2delete(1)},2)==0
                    app.Data.MultiCorPlot(app.ActiveSetSpinner.Value).DataMat(n2delete(1))=[];
                end
                app.Data.MultiCorPlot(app.ActiveSetSpinner.Value).MarkerFaceColor{n2delete(1)}(n2delete(2),:)=[];
                app.Data.MultiCorPlot(app.ActiveSetSpinner.Value).Symbol{n2delete(1)}(n2delete(2))=[];
                app.Data.MultiCorPlot(app.ActiveSetSpinner.Value).SymbolSize{n2delete(1)}(n2delete(2))=[];


                app.Data.MultiCorPlot(app.ActiveSetSpinner.Value).MarkerEdgeColor{n2delete(1)}(n2delete(2))=[];
                app.Data.MultiCorPlot(app.ActiveSetSpinner.Value).MarkerEdgeAlpha{n2delete(1)}(n2delete(2))=[];
                app.Data.MultiCorPlot(app.ActiveSetSpinner.Value).MarkerFaceAlpha{n2delete(1)}(n2delete(2))=[];

                app.Data.MultiCorPlot(app.ActiveSetSpinner.Value).LineWidth{n2delete(1)}(n2delete(2))=[];


                app.Data.MultiCorPlot(app.ActiveSetSpinner.Value).Active{n2delete(1)}(n2delete(2))=[];
                app.Data.MultiCorPlot(app.ActiveSetSpinner.Value).ClassNames{n2delete(1)}(n2delete(2))=[];
                app.Data.MultiCorPlot(app.ActiveSetSpinner.Value).ClassNames_original{n2delete(1)}(n2delete(2))=[];
                app.Data.MultiCorPlot(app.ActiveSetSpinner.Value).ResIds{n2delete(1)}(n2delete(2))=[];
                app.Data.MultiCorPlot(app.ActiveSetSpinner.Value).type{n2delete(1)}(n2delete(2))=[];


            else
                n2delete =find(strcmp(app.Tree_MultiCor_Groups.SelectedNodes.Text,{app.Tree_MultiCor_Groups.Children.Text}));

                app.Data.MultiCorPlot(app.ActiveSetSpinner.Value).DataMat(n2delete)=[];
                app.Data.MultiCorPlot(app.ActiveSetSpinner.Value).MarkerFaceColor(n2delete)=[];
                app.Data.MultiCorPlot(app.ActiveSetSpinner.Value).Symbol(n2delete)=[];

                app.Data.MultiCorPlot(app.ActiveSetSpinner.Value).MarkerEdgeColor(n2delete)=[];
                app.Data.MultiCorPlot(app.ActiveSetSpinner.Value).MarkerEdgeAlpha(n2delete)=[];
                app.Data.MultiCorPlot(app.ActiveSetSpinner.Value).MarkerFaceAlpha(n2delete)=[];

                app.Data.MultiCorPlot(app.ActiveSetSpinner.Value).classNumber(n2delete)=[];
                app.Data.MultiCorPlot(app.ActiveSetSpinner.Value).ClassNames(n2delete)=[];
                app.Data.MultiCorPlot(app.ActiveSetSpinner.Value).ClassNames_original(n2delete)=[];
                app.Data.MultiCorPlot(app.ActiveSetSpinner.Value).ResIds(n2delete)=[];
                app.Data.MultiCorPlot(app.ActiveSetSpinner.Value).type(n2delete)=[];

                app.Data.MultiCorPlot(app.ActiveSetSpinner.Value).SymbolSize(n2delete)=[];
                app.Data.MultiCorPlot(app.ActiveSetSpinner.Value).Active(n2delete)=[];
                app.ActiveSetSpinner.Value=app.ActiveSetSpinner.Value-1;

                if app.ActiveSetSpinner.Value==0 && not (isempty( app.Data.MultiCorPlot))
                    app.ActiveSetSpinner.Value=1;
                end
            end


            if isempty( app.Data.MultiCorPlot(app.ActiveSetSpinner.Value).DataMat)
                app.ActiveSetSpinnerValueChanged(app)
            else
                update_MultiCor_trees(app,'Groups')
                update_MultiCor_table(app,app.ActiveSetSpinner.Value)
            end

        end

        % Button pushed function: ClearAllButton
        function ClearAllButtonPushed(app, event)
            app.Data.MultiCorPlot=[];
            app.ActiveSetSpinner.Value=0;
            update_MultiCor_table(app,app.ActiveSetSpinner.Value)
            update_MultiCor_trees(app,0)

            app.ActiveSetSpinner.Visible=false;
            app.ActiveSetSpinnerLabel.Visible=false;
        end

        % Cell edit callback: MultiCor_table
        function MultiCor_tableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            groups=find(strcmp(app.MultiCor_table.Data.Group(indices(1)),{app.Tree_MultiCor_Groups.Children.Text}));
            for n=1:numel(groups)
                group=groups(n);
                subnames={app.Tree_MultiCor_Groups.Children(group).Children.Text};

                fn=app.MultiCor_table.ColumnName{indices(2)};
                selected_subs=0;
                if strcmp(fn,'Symbol')
                    if not(ismember(newData,{'square','diamond','pentagram','hexagram','o', '+', '*', '.', 'x', '_', '|', 's', 'd', '^', 'v', '>', '<', 'p', 'h', 'none'}))
                        message={'Only the following options are allowed: ','''square'',''diamond'',''pentagram'',''hexagram'',''o'', ''+'', ''*'', ''.'', ''x'', ''_'', ''|'', ''s'', ''d'', ''^'', ''v'', ''>'', ''<'', ''p'', ''h'', ''none'''};
                        uialert(app.AdvancedPlotModuleGUI,message,'Wrong Input','Icon','Error');
                        update_MultiCor_table(app,app.ActiveSetSpinner.Value)
                        return
                    end
                elseif strcmp(fn,'MarkerFaceAlpha') ||strcmp(fn,'MarkerEdgeAlpha')
                    if not(isnumeric(newData)) || newData>1 ||newData<0
                        message={'Value must be between 0 and 1!'};
                        uialert(app.AdvancedPlotModuleGUI,message,'Wrong Input','Icon','Error');
                        update_MultiCor_table(app,app.ActiveSetSpinner.Value)
                        return
                    end

                end
                if strcmp(fn,'Name')
                    fn='ClassNames';
                    pos2insert=find(ismember(subnames,event.PreviousData));
                else
                    all=false;
                    if not(isempty(app.MultiCor_table.Selection)) && numel(app.MultiCor_table.Selection(:,1))>1
                       
                        answer = uiconfirm(app.AdvancedPlotModuleGUI,{'Do you want to edit all selected rows?'},...
    'Edit Rows?','Options',{'Yes','No','Cancel'},'Icon','warning');

                        
%                         answer = questdlg({'Do you want to edit all selected rows?'},...
%                             '','Yes','No','Cancel','');
                        switch answer
                            case 'Yes'
                                all=true;
                            case 'No'
                                all=false;
                            case 'Cancel'
                                update_MultiCor_table(app,app.ActiveSetSpinner.Value)
                                return
                        end
                    end
                    if all==true
                        selected_subs=app.MultiCor_table.Data.Name(app.MultiCor_table.Selection(:,1));
                    else
                        selected_subs= app.MultiCor_table.Data.Name(indices(1));
                    end
                    pos2insert=find(ismember(subnames,selected_subs));
                    %  pos2insert=find(ismember(subnames,event.PreviousData));
                end
                %ath add logic test for allowed symbols and values
                %          pos2insert= find(find(strcmp(app.MultiCor_table.Data.Group(indices(1)),app.MultiCor_table.Data.Group))==indices(1));
                %                             all_groups={app.Tree_MultiCor_Groups.Children.Text};
                %
                %                             group=groups(n);
                %                             app.MultiCor_table.Data.Group(group)
                %selected_subs= app.MultiCor_table.Data.Name(indices(1));
                %
                if iscell(app.Data.MultiCorPlot(app.ActiveSetSpinner.Value).(char(fn)){group}(pos2insert))
                    app.Data.MultiCorPlot(app.ActiveSetSpinner.Value).(char(fn)){group}(pos2insert)={newData};
                else
                    app.Data.MultiCorPlot(app.ActiveSetSpinner.Value).(char(fn)){group}(pos2insert)=newData;
                    % app.Data.MultiCorPlot(app.ActiveSetSpinner.Value).(char(fn)){indices(1)}=newData;
                end
            end
            if strcmp(fn,'ClassNames')
                update_MultiCor_trees(app,'Groups')
            end

            if size(selected_subs,1)>1
                update_MultiCor_table(app,app.ActiveSetSpinner.Value)
            end
        end

        % Button down function: MultiCor_table
        function MultiCor_tableButtonDown(app, event)


            click_threshold_s = 0.5;
            if isempty(app.click_time)
                app.click_time = tic();
            else

                time_between_clicks = toc( app.click_time );
                app.click_time = tic();
                if time_between_clicks < click_threshold_s
                    % if field==Color
                    if not(any(ismember(unique(app.MultiCor_table.Selection(:,2)),6)) || any(ismember(unique(app.MultiCor_table.Selection(:,2)),9)))
                        return
                    end
                    c = uisetcolor;  % Enter desired callback / action here.
                    figure(app.AdvancedPlotModuleGUI)

                    if any(ismember(unique(app.MultiCor_table.Selection(:,2)),6))
                        %    app.MultiCor_table.Data.MarkerFaceColor(app.MultiCor_table.Selection(1,:))=repmat(c',1,numel(unique(app.MultiCor_table.Selection(1,:))));

                        %  app.MultiCor_table.Data.MarkerFaceColor(app.MultiCor_table.Selection(app.MultiCor_table.Selection(:,2)==6,1),:)=repmat(c,numel(unique(app.MultiCor_table.Selection(app.MultiCor_table.Selection(:,2)==6,1))),1);
                        indices=unique(app.MultiCor_table.Selection(app.MultiCor_table.Selection(:,2)==6,1));

                        groups=find(ismember({app.Tree_MultiCor_Groups.Children.Text},app.MultiCor_table.Data.Group(indices)));%unique(app.MultiCor_table.Data.Group(ismember(app.MultiCor_table.Data.Group(indices),{app.Tree_MultiCor_Groups.Children.Text})));
                        %find(strcmp(app.MultiCor_table.Data.Group(indices),{app.Tree_MultiCor_Groups.Children.Text}));
                        all_groups={app.Tree_MultiCor_Groups.Children.Text};
                        for n=1:numel(groups)
                            group=groups(n);
                            app.MultiCor_table.Data.Group(group)
                            subnames={app.Tree_MultiCor_Groups.Children(group).Children.Text};
                            % strcmp(app.MultiCor_table.Data.Group(indices),all_groups(group))
                            selected_subs= app.MultiCor_table.Data.Name(indices);
                            pos2insert=find(ismember(subnames,selected_subs));
                            %                       pos2insert= find(ismember({app.Tree_MultiCor_Groups.Children(group).Children.Text},app.MultiCor_table.Data.Name(find(strcmp(app.MultiCor_table.Data.Group(indices),app.MultiCor_table.Data.Group(group))))));%find(find(strcmp(app.MultiCor_table.Data.Group(indices),app.MultiCor_table.Data.Group))==indices);
                            app.Data.MultiCorPlot(app.ActiveSetSpinner.Value).MarkerFaceColor{group}(pos2insert,:)=repmat(c,numel(unique(pos2insert)),1);
                        end
                    end
                    if any(ismember(unique(app.MultiCor_table.Selection(:,2)),9))
                        %    app.MultiCor_table.Data.MarkerFaceColor(app.MultiCor_table.Selection(1,:))=repmat(c',1,numel(unique(app.MultiCor_table.Selection(1,:))));

                        % app.MultiCor_table.Data.MarkerEdgeColor(app.MultiCor_table.Selection(:,1),:)=repmat(c,numel(unique(app.MultiCor_table.Selection(:,1))),1);
                        indices=unique(app.MultiCor_table.Selection(app.MultiCor_table.Selection(:,2)==9,1));

                        groups=find(ismember({app.Tree_MultiCor_Groups.Children.Text},app.MultiCor_table.Data.Group(indices)));%unique(app.MultiCor_table.Data.Group(ismember(app.MultiCor_table.Data.Group(indices),{app.Tree_MultiCor_Groups.Children.Text})));
                        %find(strcmp(app.MultiCor_table.Data.Group(indices),{app.Tree_MultiCor_Groups.Children.Text}));
                        all_groups={app.Tree_MultiCor_Groups.Children.Text};
                        for n=1:numel(groups)
                            group=groups(n);
                            app.MultiCor_table.Data.Group(group)
                            subnames={app.Tree_MultiCor_Groups.Children(group).Children.Text};
                            % strcmp(app.MultiCor_table.Data.Group(indices),all_groups(group))
                            selected_subs= app.MultiCor_table.Data.Name(indices);
                            pos2insert=find(ismember(subnames,selected_subs));
                            %                       pos2insert= find(ismember({app.Tree_MultiCor_Groups.Children(group).Children.Text},app.MultiCor_table.Data.Name(find(strcmp(app.MultiCor_table.Data.Group(indices),app.MultiCor_table.Data.Group(group))))));%find(find(strcmp(app.MultiCor_table.Data.Group(indices),app.MultiCor_table.Data.Group))==indices);
                            app.Data.MultiCorPlot(app.ActiveSetSpinner.Value).MarkerEdgeColor{group}(pos2insert,:)=repmat(c,numel(unique(pos2insert)),1);
                        end

                    end
                    update_MultiCor_table(app,app.ActiveSetSpinner.Value)

                    %  end
                end % if double-click
            end % if empty


        end

        % Value changed function: AllSettingsCheckBox, ColorPreviewCheckBox
        function ColorPreviewCheckBoxValueChanged(app, event)
            app.ExportStyleButton.Visible=app.AllSettingsCheckBox.Value;
            app.ReadStyleButton.Visible=app.AllSettingsCheckBox.Value;
            update_MultiCor_table(app,app.ActiveSetSpinner.Value)
        end

        % Value changed function: ActiveSetSpinner
        function ActiveSetSpinnerValueChanged(app, event)
            value = app.ActiveSetSpinner.Value;

            if numel(app.Data.MultiCorPlot)<value
                app.ActiveSetSpinner.Value=numel(app.Data.MultiCorPlot);
            end
            if value<=0
                if numel(app.Data.MultiCorPlot)>0
                    app.ActiveSetSpinner.Value=1;
                else
                    app.ActiveSetSpinner.Value=0;
                end
            end
            update_MultiCor_table(app,app.ActiveSetSpinner.Value)
            update_MultiCor_trees(app,'Groups')

        end

        % Button pushed function: MaskButton
        function MaskButtonPushed(app, event)
            %             checkedNodes = app.Tree.CheckedNodes;
            %             selectedNodes = app.Tree.SelectedNodes;
            old_mask=app.Full_T.Data.Active;

            new_mask=zeros(size(old_mask));
            new_mask(app.ResultsTable.Data.ResId(app.ResultsTable.Data.Active==true))=true;

            app.Full_T.Data.Active(:)=(old_mask==true&new_mask==true);

            update_class_table(app)

            update_results_table(app)
            UpdateTreeMinSample(app,app.Tree)

            update_cmap_table(app)
            Minerals=unique(app.Full_T.Data.Mineral(app.Full_T.Data.Active==true,:));
            update_plot_selection_tree(app,Minerals)
        end

        % Button pushed function: ResetMaskButton_2
        function ResetMaskButton_2Pushed(app, event)
            app.Full_T.Data.Active(:)=true;
            update_class_table(app)
            update_results_table(app)
            UpdateTreeMinSample(app,app.Tree)
            update_cmap_table(app)
            Minerals=unique(app.Full_T.Data.Mineral(app.Full_T.Data.Active==true,:));
            update_plot_selection_tree(app,Minerals)
        end

        % Button pushed function: PlotSelectedButton
        function PlotSelectedButtonPushed(app, event)
            d = uiprogressdlg(app.AdvancedPlotModuleGUI,'Title','Generating Plots...',...
                'Message','Plotting started','Cancelable','on');

            update_MultiCor_table(app,app.ActiveSetSpinner.Value)
            plot_stacked_binary=true;

            CheckedNodes=app.Tree2.CheckedNodes;
            if isempty(CheckedNodes)
                return
            end
            Mineral={app.Tree2.Children.Text};

%             %ATH here panelty if Pyroxene / CPX / OPX
%              if any(ismember(Mineral,'pyroxene'))
%             Mineral{end+1}='clinopyroxene';
%             Mineral{end+1}='orthopyroxene';
%             Mineral=unique(Mineral);
%              end

            for n=1:numel(Mineral)

                if (any(ismember({app.Tree2.CheckedNodes.Text},'Stacked Binary Plots')) && plot_stacked_binary)
                    %
                elseif not(any(contains(Mineral{n},{CheckedNodes.Text}))) && not(any(startsWith({CheckedNodes.Text},Mineral{n}))) && not(strcmp('pyroxene',Mineral{n}) && any(startsWith({CheckedNodes.Text},'clinopyroxene')))
                    continue
                end
                
                DataIn=app.Data.MultiCorPlot(app.ActiveSetSpinner.Value);

                %for set_val=1:numel(DataIn.DataMat)
                checkedNodes_Groups = app.Tree_MultiCor_Groups.CheckedNodes;

                if not(isempty(checkedNodes_Groups))

                    checkedNodes_Groups = app.Tree_MultiCor_Groups.CheckedNodes(cellfun(@isnumeric,{app.Tree_MultiCor_Groups.CheckedNodes.NodeData}));

                    nd=reshape([checkedNodes_Groups.NodeData],2,[])';

                    nodes={app.Tree_MultiCor_Groups.Children(:).Children};
                    nodes=cat(1,nodes{:});
                    nd_all=reshape([nodes.NodeData],2,[])';

                    for k=1:numel(unique(nd_all(:,1)),'stable')
                        test= ismember(nd_all(nd_all(:,1)==k,:),nd,'rows');
                        % subset_var=test;

                        not_subgroup=find(not(test));
                        % DataIn.Data{k}.(DataIn.ClassNames{k}(not_subgroup))=[];

                        if isempty(not_subgroup)
                            continue
                        end

                        DataIn.Active{k}(not_subgroup)=[];%:
                        DataIn.MarkerFaceColor{k}(not_subgroup,:)=[];%:
                        DataIn.Symbol{k}(not_subgroup)=[];%:

                        DataIn.MarkerEdgeColor{k}(not_subgroup,:)=[];%:
                        DataIn.MarkerEdgeAlpha{k}(not_subgroup)=[];%:
                        DataIn.MarkerFaceAlpha{k}(not_subgroup)=[];%:

                        DataIn.LineWidth{k}(not_subgroup)=[];%:


                        DataIn.ClassNames_original{k}(not_subgroup)=[];%:
                        DataIn.ClassNames{k}(not_subgroup)=[];%:

                        DataIn.SymbolSize{k}(not_subgroup)=[];%:

                        DataIn.DataMat{k}(ismember(DataIn.classNumber{k},not_subgroup),:)=[];
                        DataIn.classNumber{k}(ismember(DataIn.classNumber{k},not_subgroup))=[];
                        % EdgeColor
                    end

                end
                %  end

                %%
                %  for set_val=unique(nd(:,1))
                set_val=app.ActiveSetSpinner.Value;

                if any(ismember({app.Tree2.CheckedNodes.Text},'Stacked Binary Plots')) && plot_stacked_binary
                    checked_nodes_var=app.Tree_MultiCor_VarName.CheckedNodes;

                    if numel(checked_nodes_var)>12 || numel(DataIn.ClassNames{set_val})>30
                        selection = uiconfirm(app.AdvancedPlotModuleGUI, ...
                            "More than 12 Variables and/or more than 30 Classes selected. This might take a long time are you sure you want to proceed?","Confirm Plot", ...
                            "Icon","warning");
                        if strcmp(selection,'Cancel')
                            return
                        end


                    end

                    if not(isempty(checked_nodes_var))
                        wanted_Vars=find(ismember(DataIn.VarNames,{checked_nodes_var.Text}));
                    else
                        wanted_Vars=[];
                    end

                    for p=wanted_Vars

                        options.stacked_binary.x_label_str=DataIn.VarNames{p};
                        ucn=unique(DataIn.classNumber{set_val},'stable');

                        [ax2plot,~,~] = plot_stacked_binary_data_fun([],[],1);
                        for kk=1:numel(DataIn.ClassNames{set_val})
                            %%
                            options.plot_settings.MarkerFaceColor=DataIn.MarkerFaceColor{set_val}(kk,:);
                            options.plot_settings.symbol=DataIn.Symbol{set_val}{kk};
                            options.plot_settings.symbol_size=DataIn.SymbolSize{set_val}(kk)*20;

                            options.plot_settings.symbol=DataIn.Symbol{set_val}{kk};
                            options.plot_settings.symbol=DataIn.Symbol{set_val}{kk};
                            if app.AllSettingsCheckBox.Value
                                options.plot_settings.MarkerEdgeColor=DataIn.MarkerEdgeColor{set_val}(kk,:);
                                options.plot_settings.MarkerEdgeAlpha.Value=DataIn.MarkerEdgeAlpha{set_val}(kk);
                                options.plot_settings.MarkerFaceAlpha.Value=DataIn.MarkerFaceAlpha{set_val}(kk);

                                options.plot_settings.MarkerLineWidth.Value=DataIn.LineWidth{set_val}(kk);

                            else
                                options.plot_settings.MarkerEdgeColor=[];
                                options.plot_settings.MarkerEdgeAlpha.Value=[];
                                options.plot_settings.MarkerFaceAlpha.Value=[];

                                options.plot_settings.MarkerLineWidth.Value=[];


                            end
                            data_thx=app.Full_T.Data(DataIn.ResIds{set_val},:);
                            if strcmp(DataIn.type{set_val},'mean')
                                data_thx2=data_thx(1,:);
                                data_thx2(1,:)=[];

                                data_thx2(1,vartype("numeric"))=varfun(@(x) mean(x,'omitnan'), data_thx, 'InputVariables', @isnumeric);
                                options.stacked_binary.X1=data_thx2;

                            elseif strcmp(DataIn.type{set_val},'median')
                                data_thx2=data_thx(1,:);
                                data_thx2(1,:)=[];
                                data_thx2(1,vartype("numeric"))=varfun(@(x) median(x,'omitnan'), data_thx, 'InputVariables', @isnumeric);
                                options.stacked_binary.X1=data_thx2;

                            else
                                options.stacked_binary.X1=DataIn.DataMat{set_val}(DataIn.classNumber{set_val}==ucn(kk),p);
                            end

                            %%
                            options.stacked_binary.y_label_str=DataIn.ClassNames{set_val};
                            options.stacked_binary.n=kk;
                            [ax2plot,~,~] = plot_stacked_binary_data_fun([],ax2plot,options);
                        end
                        ax2plot(1).Parent(1).Parent(1).Position(4)=ax2plot(1).Parent(1).Parent(1).Position(4)*2;

                    end
                    plot_stacked_binary=false;

                end
                %%


                options.XYplot.Value=true;
                options.plot_tern.Value=true;

                if not(isempty(app.Tree2.Children(n).Children))
                    plot_type={app.Tree2.Children(n).Children.Text};
                else
                    plot_type={};
                end

                for l=1:numel(plot_type)

                    plotoptions=app.Tree2.Children(n).Children(l).Children(ismember(app.Tree2.Children(n).Children(l).Children,[app.Tree2.CheckedNodes]));
                    if isempty(plotoptions)
                        continue
                    else
                        plotoptions={plotoptions.NodeData};
                    end

                    for t=1:numel(plotoptions)
                        ax2plot=[];
                        legend_str={};
                        for set_val=1:numel(DataIn.DataMat)
                            options.type.Value=plotoptions{t};
                            for k=1:numel(DataIn.ClassNames{set_val})


                                if d.CancelRequested
                                    break
                                end


                                if isempty(DataIn.ClassNames{set_val}) || isempty(DataIn.DataMat{set_val})
                                    continue
                                end


                                if isempty(ax2plot)
                                    options.plot_state=true;
                                    if strcmp('Ternary',plot_type{l})
                                        options.background_FaceAlpha.Value=1;
                                        [ax2plot,~]=plot_tern_fun([],options);
                                    elseif strcmp('XY Plot',plot_type{l})
                                        [ax2plot,~]=plot_XY_fun([],options);
                                    elseif strcmp('Multi XY Plot',plot_type{l})
                                        [ax2plot,~]=plot_multi_XY_fun([],options);
                                    elseif strcmp('Binary Plot',plot_type{l})
                                        [ax2plot,~]=plot_binary_fun([],options);

                                    end


                                end

                                data_thx=app.Full_T.Data(DataIn.ResIds{set_val},:);
                                try
                                    R_Id_thx=DataIn.ResIds{set_val};
                                    R_Id_thx(strcmp(data_thx.(char(DataIn.ClassCol{set_val})),DataIn.ClassNames_original{set_val}(k)));
                                    data_thx=data_thx(strcmp(data_thx.(char(DataIn.ClassCol{set_val})),DataIn.ClassNames_original{set_val}(k)),:);

                                    for kk=1:numel(data_thx.Mineral)
                                        data_thx.Mineral(kk) = {CheckMinName(app.MinPlotX,data_thx.Mineral(kk))};
                                    end

                                    data_thx=data_thx(ismember(data_thx.Mineral,Mineral(n)),:);

                                catch ME
                                    DisplayErrorNG(app.MinPlotX,ME,'PlotSelected Error 01')

                                end

                                if isempty(data_thx) || size(data_thx,1)==0
                                    continue
                                end

                                d.Value = k/numel(DataIn.ClassNames{set_val});
                                d.Message = sprintf(['Mineral: (' num2str(n) ' / ' num2str(numel(Mineral)) ') ' Mineral{n} '\n'...
                                    'Plot (' num2str(l) ' / ' num2str(numel(plot_type)) ') ' char(plot_type{l}) '\n'...
                                    'Plotoptions (' num2str(t) ' / ' num2str(numel(plotoptions)) ') ' char(plotoptions{t}) '\n'...
                                    'Set (' num2str(set_val) ' / ' num2str(numel(DataIn.DataMat)) ')\n'...
                                    'Class (' num2str(k) ' / ' num2str(numel(DataIn.ClassNames{set_val})) ')\n'...
                                    ]);


                                if strcmp(DataIn.type{set_val},'mean')
                                    data_thx2=data_thx(1,:);
                                    data_thx2(1,:)=[];

                                    data_thx2(1,vartype("numeric"))=varfun(@(x) mean(x,'omitnan'), data_thx, 'InputVariables', @isnumeric);
                                    data_thx=data_thx2;

                                elseif strcmp(DataIn.type{set_val},'median')
                                    data_thx2=data_thx(1,:);
                                    data_thx2(1,:)=[];
                                    data_thx2(1,vartype("numeric"))=varfun(@(x) median(x,'omitnan'), data_thx, 'InputVariables', @isnumeric);
                                    data_thx=data_thx2;
                                end


                                options.plot_settings.MarkerFaceColor=DataIn.MarkerFaceColor{set_val}(k,:);
                                options.plot_settings.symbol=DataIn.Symbol{set_val}{k};
                                options.plot_settings.symbol_size=DataIn.SymbolSize{set_val}(k)*20;

                                options.plot_settings.symbol=DataIn.Symbol{set_val}{k};
                                options.plot_settings.symbol=DataIn.Symbol{set_val}{k};
                                if app.AllSettingsCheckBox.Value
                                    options.plot_settings.MarkerEdgeColor=DataIn.MarkerEdgeColor{set_val}(k,:);
                                    options.plot_settings.MarkerEdgeAlpha.Value=DataIn.MarkerEdgeAlpha{set_val}(k);
                                    options.plot_settings.MarkerFaceAlpha.Value=DataIn.MarkerFaceAlpha{set_val}(k);

                                    options.plot_settings.MarkerLineWidth.Value=DataIn.LineWidth{set_val}(k);

                                else
                                    options.plot_settings.MarkerEdgeColor=[];
                                    options.plot_settings.MarkerEdgeAlpha.Value=[];
                                    options.plot_settings.MarkerFaceAlpha.Value=[];

                                    options.plot_settings.MarkerLineWidth.Value=[];

                                end


                                options.legend.String= DataIn.ClassNames{set_val}(k);

                                if strcmp('Ternary',plot_type{l})
                                    try

                                        [ax2plot,~,legend_str_thx] = plot_tern_data_fun(data_thx,ax2plot,options);
                                        if false% k==numel(app.cmap_table.Data.Active)
                                            options.background_FaceAlpha.Value=0;
                                            [ax2plot,~,legend_str_thx]=plot_tern_fun(ax2plot,options);
                                        end
                                        legend_str=[legend_str legend_str_thx];

                                    catch ME

                                        DisplayErrorNG(app.MinPlotX,ME,'PlotSelected Error 02')
                                    end

                                elseif  strcmp('XY Plot',plot_type{l})

                                    try
                                        [ax2plot,~,legend_str_thx] = plot_XY_data_fun(data_thx,ax2plot,options);
                                        legend_str=[legend_str legend_str_thx];

                                    catch ME
                                        DisplayErrorNG(app.MinPlotX,ME,'PlotSelected Error 03')

                                    end
                                elseif strcmp('Multi XY Plot',plot_type{l})

                                    try
                                        [ax2plot,~,legend_str_thx] = plot_multi_XY_data_fun(data_thx,ax2plot,options);
                                        legend_str=[legend_str legend_str_thx];

                                    catch ME
                                        DisplayErrorNG(app.MinPlotX,ME,'PlotSelected Error 04')

                                    end
                                elseif strcmp('Binary Plot',plot_type{l})

                                    try
                                        [ax2plot,~,legend_str_thx] = plot_binary_data_fun(data_thx,ax2plot,options);
                                        legend_str=[legend_str legend_str_thx];


                                    catch ME
                                        DisplayErrorNG(app.MinPlotX,ME,'PlotSelected Error 05')

                                    end


                                end

                            end


                            % title(ax2plot,plotoptions{t},'interpreter','none')
                            if not(isempty(ax2plot)) && strcmp('Binary Plot',plot_type{l})
                                ax2plot(1).Parent(1).Parent.Position(4)=ax2plot(1).Parent(1).Parent.Position(4)/2;
                                ax2plot(1).Parent(1).Parent.Position(3)=ax2plot(1).Parent(1).Parent.Position(3)*1.5;
                            elseif not(isempty(ax2plot)) && strcmp('Multi XY Plot',plot_type{l})
                            elseif not(isempty(ax2plot))
                                ax2plot(1).Parent(1).Parent.Position(3:4)=ax2plot(1).Parent(1).Parent.Position(3:4)*2;
                            end

                            if numel(legend_str)>0
                                if numel(ax2plot)>1
                                    pos_ax=reshape([ax2plot.Position],4,[])';
                                    if numel(unique(pos_ax(:,1)))>1
                                        [~,  max_col]=max(pos_ax(:,2));
                                        pos_ax(:,not(max_col))=nan;
                                        [~, max_row]=max(pos_ax(:,1));
                                    else
                                        max_row=1;
                                    end

                                    thx=[];
                                    for nn=1:numel(ax2plot)
                                        thx=unique([thx; matlab.graphics.illustration.internal.getLegendableChildren(ax2plot(nn))],'stable');
                                    end
                                    if not(isempty(thx))

                                        for n_symbols=1:numel(thx)
                                            hold(ax2plot(max_row),'on')
                                            l_h(n_symbols)=scatter(ax2plot(max_row),nan,nan,'filled',thx(n_symbols).Marker,'SizeData',thx(n_symbols).SizeData,'LineWidth',thx(n_symbols).LineWidth,'MarkerFaceColor',thx(n_symbols).MarkerFaceColor, 'MarkerEdgeColor',thx(n_symbols).MarkerEdgeColor,'CData',thx(n_symbols).CData,'MarkerFaceAlpha',thx(n_symbols).MarkerFaceAlpha,'MarkerEdgeAlpha',thx(n_symbols).MarkerEdgeAlpha);
                                        end

                                        %   lgnd=legend(ax2plot(max_row),l_h,legend_str,'location','northoutside','interpreter','none','Orientation','vertical');
                                        lgnd=legend(ax2plot(max_row),l_h,legend_str,'location','eastoutside','interpreter','none','Orientation','vertical');
                                    end

                                    %  legend(ax2plot(max_row),thx,legend_str,'location','northeastoutside','interpreter','none');
                                else
                                    %      legend(legend_str,'location','northeastoutside','interpreter','none');
                                    legend(legend_str,'location','eastoutside','interpreter','none');
                                end
                                %                          try
                                %                              ls=join([DataIn.ClassNames(legend_test) repmat({''},size(DataIn.ClassNames(legend_test)))]);
                                %                              [h,icons,plots,legend_text]=legend(ls,'location','northeastoutside','interpreter','none');
                                %                             h.ItemTokenSize = [10,5];
                                %                             icons = findobj(icons,'Type','patch');
                                %                             icons = findobj(icons,'Marker','none','-xor');
                                %                             for n_ics=1:numel(icons)
                                %                                 icons(n_ics).MarkerSize=icons(n_ics).MarkerSize*1.5;
                                %                             end
                                %                          catch
                                %                              0
                                %                          end


                                %% ADD EASYPLOT
                                if strcmp('Multi XY Plot',plot_type{l})
                                    %ATH this will be implemented in the future
                                    %                                     
                                    %
                                    %
                                else
                                    try
                                    if not(isfield(app.MinPlotX.MinPlotXFigures,'hFig_type')) || isempty(app.MinPlotX.MinPlotXFigures) || isempty(app.MinPlotX.MinPlotXFigures.hFig_type) % or handle not valid
                                        hFig_id=1;
                                    else
                                        hFig_id=numel(app.MinPlotX.MinPlotXFigures.hFig_type)+1;
                                    end
                                    if isfield(app.MinPlotX.MinPlotXFigures,'hFig_type') && not(isempty(app.MinPlotX.MinPlotXFigures.hFig_type)) && ismember(options.type.Value,app.MinPlotX.MinPlotXFigures.hFig_type)
                                        names_unique = matlab.lang.makeUniqueStrings([app.MinPlotX.MinPlotXFigures.hFig_type options.type.Value]);
                                        type_unique=names_unique{end};
                                    else
                                        type_unique=options.type.Value;

                                    end

                                    app.MinPlotX.MinPlotXFigures.hFig(hFig_id).options=options;
                                    app.MinPlotX.MinPlotXFigures.hFig_type{hFig_id}=char(type_unique);
                                    app.MinPlotX.MinPlotXFigures.hFig_description{hFig_id}=['Advanced Plot ' char(type_unique)];
                                    try
                                        hfig=ax2plot(1).Parent(1).Parent(1);
                                        hfig.CloseRequestFcn = @(src, event) EasyPlotCloseRequestFcn(app.MinPlotX,src, event);
                                    catch
                                        hfig=ax2plot(1).Parent(1);
                                        hfig.CloseRequestFcn = @(src, event) EasyPlotCloseRequestFcn(app.MinPlotX,src, event);
                                    end
                                    app.MinPlotX.MinPlotXFigures.hFig(hFig_id).ax2plot=ax2plot;
                                    hfig.Name=app.MinPlotX.MinPlotXFigures.hFig_description{hFig_id};

                                catch ME
                                    DisplayErrorNG(app.MinPlotX,ME,'PlotSelected Error 06')
                                end
                                end
                            end
                        end
                    end
                end
            end

            delete(d)
            callEasyPlotModule(app.MinPlotX)

        end

        % Drop down opening function: VariableDropDown
        function VariableDropDownOpening(app, event)
            tic
            T_Thx=app.Full_T.Data(app.Full_T.Data.Active,:);
            T_Thx2=T_Thx(1,any(cellfun(@ischar,table2cell(T_Thx)))&not(sum(cellfun(@isempty,table2cell(T_Thx)))==size(T_Thx,1)));
            app.VariableDropDown.Items=T_Thx2.Properties.VariableNames;
            toc

        end

        % Menu selected function: SaveMenu
        function SaveMenuSelected(app, event)
            f = figure('Renderer', 'painters', 'Position', [-100 -100 0 0]);
            [filename, filepath] = uiputfile('*.mat', 'Save the project file: ');
            delete(f);
            figure(app.AdvancedPlotModuleGUI);

            if not(isequal(filename,0))
                FileName = fullfile(filepath, filename);

                data2save=app.Data;
                save(FileName, 'data2save', '-v7.3');
            end

        end

        % Menu selected function: LoadMenu
        function LoadMenuSelected(app, event)

            f = figure('Renderer', 'painters', 'Position', [-100 -100 0 0]);
            [file,path]=uigetfile({'*.mat'},"MultiSelect","off", 'Open Project');
            delete(f);
            figure(app.AdvancedPlotModuleGUI);

            if not(isequal(file,0))
                thx=load(fullfile(path,file));
                app.Data=thx.data2save;
            else
                return
            end

            app.ActiveSetSpinner.Value=1;

            update_MultiCor_trees(app,0)
            update_MultiCor_table(app,app.ActiveSetSpinner.Value)

            Minerals=unique(app.Full_T.Data.Mineral(app.Full_T.Data.Active==true,:));
            update_plot_selection_tree(app,Minerals)
        end

        % Button pushed function: HistogramFilterButton
        function HistogramFilterButtonPushed(app, event)
            fun=@(app,state)(update_1_public(app,state));
            HistogramFilterModule(app,app.Full_T,fun)

        end

        % Button pushed function: NewTernPlotButton
        function NewTernPlotButtonPushed(app, event)

            update_MultiCor_table(app,app.ActiveSetSpinner.Value)
            if isempty(app.Data.MultiCorPlot)
                return
            end
            DataIn=app.Data.MultiCorPlot(app.ActiveSetSpinner.Value);
            DataIn.link=app.LinkAxesCheckBox.Value;
            DataIn.ExcludeEmpty=app.ExcludeEmptyCheckBox.Value;
DataIn.cmap=DataIn.MarkerFaceColor;

            checkedNodes_var = app.Tree_MultiCor_Groups.CheckedNodes;
            checkedNodes_groups = app.Tree_MultiCor_VarName.CheckedNodes;


            if not(isempty(checkedNodes_groups))
                checkedNodes_groups = app.Tree_MultiCor_VarName.CheckedNodes(cellfun(@isnumeric,{app.Tree_MultiCor_VarName.CheckedNodes.NodeData}));

                nd=reshape([checkedNodes_groups.NodeData],2,[])';
                set_var=unique(nd(:,1));
                for n=1:numel(set_var)
                    subset_var=nd(nd(:,1)==set_var(n),2);
                    n_sg=1:numel(DataIn.VarNames);

                    not_subgroup=n_sg(not(ismember(n_sg,subset_var)));
                    %                      DataIn.DataMat{set_var}(:,not_subgroup)=[];
                    %                      DataIn.VarNames(not_subgroup)=[];
                    for k=1:numel(DataIn.DataMat)
                        DataIn.DataMat{k}(:,not_subgroup)=[];
                    end
                    DataIn.VarNames(not_subgroup)=[];

                end
            end

            if not(isempty(checkedNodes_var))

                checkedNodes_var = app.Tree_MultiCor_Groups.CheckedNodes(cellfun(@isnumeric,{app.Tree_MultiCor_Groups.CheckedNodes.NodeData}));

                nd=reshape([checkedNodes_var.NodeData],2,[])';

                nodes={app.Tree_MultiCor_Groups.Children(:).Children};
                nodes=cat(1,nodes{:});
                nd_all=reshape([nodes.NodeData],2,[])';

                for n=1:size(unique(nd_all(:,1)))
                    test= ismember(nd_all(nd_all(:,1)==n,:),nd,'rows');
                    % subset_var=test;

                    not_subgroup=find(not(test));

                    DataIn.Active{n}(not_subgroup)=[];%:
                    DataIn.cmap{n}(not_subgroup,:)=[];%:
                    DataIn.MarkerFaceColor{n}(not_subgroup,:)=[];%:
                    DataIn.Symbol{n}(not_subgroup)=[];%:
                    DataIn.ClassNames{n}(not_subgroup)=[];%:
                    DataIn.SymbolSize{n}(not_subgroup)=[];%:


                    DataIn.DataMat{n}(ismember(DataIn.classNumber{n},not_subgroup),:)=[];
                    DataIn.classNumber{n}(ismember(DataIn.classNumber{n},not_subgroup))=[];
                    % EdgeColor
                end

            end

            if  app.ExcludeEmptyCheckBox.Value==true

            end

            if app.Norm_CheckBox_2.Value==true
                for n=1:numel(DataIn.DataMat)
                    DataIn.DataMat{n}=normalize(DataIn.DataMat{n},'range');
                end
            end

            if app.PlotDataCheckBox.Value==true%
                PlotTernFun(DataIn,app.MaxSubpltSpinner.Value);
            end

            if app.PlotsqrtCheckBox.Value==true
                DataIn_sqrt=DataIn;
                for n=1:numel(DataIn_sqrt.DataMat)
                    DataIn_sqrt.DataMat{n}=normalize( DataIn_sqrt.DataMat{n},'range');
                    DataIn_sqrt.DataMat{n}=sqrt(DataIn_sqrt.DataMat{n});

                end
                PlotTernFun(DataIn_sqrt,app.MaxSubpltSpinner.Value);
                ts=sgtitle('sqrt');
                set(ts, 'Interpreter', 'none')
            end

            if app.PlotLogCheckBox.Value==true
                DataIn_log=DataIn;
                for n=1:numel(DataIn_log.DataMat)
                    DataIn_log.DataMat{n}=normalize( DataIn_log.DataMat{n},'range');
                    DataIn_log.DataMat{n}=log(DataIn_log.DataMat{n});

                end
                PlotTernFun(DataIn_log,app.MaxSubpltSpinner.Value);

            end
            if app.PlotCLRCheckBox.Value==true
                DataIn_clr=DataIn;

                for n=1:numel(DataIn_clr.DataMat)
                    DataIn_clr.DataMat{n}=normalize( DataIn_clr.DataMat{n},'range');
                    DataIn_clr.DataMat{n}=clr(DataIn_clr.DataMat{n});
                end

                PlotTernFun(DataIn_clr,app.MaxSubpltSpinner.Value);
            end

        end

        % Value changed function: ShowFull_TCheckBox
        function ShowFull_TCheckBoxValueChanged(app, event)
            app.Full_T.Visible = app.ShowFull_TCheckBox.Value;

        end

        % Button pushed function: ExportStyleButton
        function ExportStyleButtonPushed(app, event)
             VarNames={'Active','Name','Symbol','SymbolSize','MarkerFaceColor_r','MarkerFaceColor_g','MarkerFaceColor_b','MarkerFaceAlpha','MarkerEdgeColor_r','MarkerEdgeColor_g','MarkerEdgeColor_b','MarkerEdgeAlpha','LineWidth'};

            T=table();
            MultiCorGroup=1;
            for n=1:numel({app.Data.MultiCorPlot(MultiCorGroup).Active{:}})
                T=[T; table(app.Data.MultiCorPlot(MultiCorGroup).Active{n}, app.Data.MultiCorPlot(MultiCorGroup).ClassNames{n}, ...
                    app.Data.MultiCorPlot(MultiCorGroup).Symbol{n}, app.Data.MultiCorPlot(MultiCorGroup).SymbolSize{n},...
                    app.Data.MultiCorPlot(MultiCorGroup).MarkerFaceColor{n}(:,1),app.Data.MultiCorPlot(MultiCorGroup).MarkerFaceColor{n}(:,2),app.Data.MultiCorPlot(MultiCorGroup).MarkerFaceColor{n}(:,3),...
                    app.Data.MultiCorPlot(MultiCorGroup).MarkerFaceAlpha{n},...
                    app.Data.MultiCorPlot(MultiCorGroup).MarkerEdgeColor{n}(:,1),app.Data.MultiCorPlot(MultiCorGroup).MarkerEdgeColor{n}(:,2),app.Data.MultiCorPlot(MultiCorGroup).MarkerEdgeColor{n}(:,3),...
                    app.Data.MultiCorPlot(MultiCorGroup).MarkerEdgeAlpha{n},app.Data.MultiCorPlot(MultiCorGroup).LineWidth{n},VariableNames=VarNames)];
            end


            folder = pwd;
                    f = figure('Renderer', 'painters', 'Position', [-100 -100 0 0]);
                    [file, folder] = uiputfile(fullfile(folder, '*.csv'), 'Save Style Table');
                    if file == 0
                    	return;
                    end
                    delete(f);
                    figure(app.AdvancedPlotModuleGUI);
                    [~, FileNameNoExt, ~] = fileparts(file);
                    fullFileName = fullfile(folder, [FileNameNoExt, '.csv']);
                    writetable(T, fullFileName);

        end

        % Button pushed function: ReadStyleButton
        function ReadStyleButtonPushed(app, event)
            f = figure('Renderer', 'painters', 'Position', [-100 -100 0 0]);
            [file,path]=uigetfile({'*.csv'},"MultiSelect","off", 'Import Style Table');
            delete(f);
            figure(app.AdvancedPlotModuleGUI);

            if isequal(file,0)
                return
            end

            T= readtable(fullfile(path,file));
            MultiCorGroup=1;

           T.ClassNames=T.Name;
            for n=1:numel(T.ClassNames)

                for group=1:numel({app.Data.MultiCorPlot(MultiCorGroup).ClassNames{:}})
                    idx=strcmp(T.ClassNames(n),app.Data.MultiCorPlot(MultiCorGroup).ClassNames{group});
                    if sum(idx)>0
                        if ismember('Active',T.Properties.VariableNames)
                            app.Data.MultiCorPlot(MultiCorGroup).Active{group}(idx)=T.Active(n);
                        end

                        app.Data.MultiCorPlot(MultiCorGroup).Symbol{group}(idx)=T.Symbol(n);
                        app.Data.MultiCorPlot(MultiCorGroup).SymbolSize{group}(idx)=T.SymbolSize(n);


                        app.Data.MultiCorPlot(MultiCorGroup).MarkerFaceColor{group}(idx,:)=[T.MarkerFaceColor_r(n) T.MarkerFaceColor_g(n) T.MarkerFaceColor_b(n)];

                        app.Data.MultiCorPlot(MultiCorGroup).MarkerFaceAlpha{group}(idx)=T.MarkerFaceAlpha(n);


                        app.Data.MultiCorPlot(MultiCorGroup).MarkerEdgeColor{group}(idx,:)=[T.MarkerEdgeAlpha(n) T.MarkerEdgeAlpha(n) T.MarkerEdgeAlpha(n)];
                        app.Data.MultiCorPlot(MultiCorGroup).MarkerEdgeAlpha{group}(idx)=T.MarkerEdgeAlpha(n);
                        app.Data.MultiCorPlot(MultiCorGroup).LineWidth{group}(idx)=T.LineWidth(n);
                    end
                end
            end
            update_MultiCor_table(app,app.ActiveSetSpinner.Value)

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.AdvancedPlotModuleGUI = uifigure('Visible', 'off');
            app.AdvancedPlotModuleGUI.Name = 'AdvancedPlotModule';
            app.GridLayout16 = uigridlayout(app.AdvancedPlotModuleGUI);
            app.TabGroup = uitabgroup(app.GridLayout16);
            app.ResultsTab = uitab(app.TabGroup);
            app.ResultsTab.Title = '1) Data Table';
            app.GridLayout14 = uigridlayout(app.ResultsTab);
            app.ResultsTable = uitable(app.GridLayout14);
            app.ResultsTable.ColumnName = '';
            app.ResultsTable.RowName = {};
            app.ResetColFilt = uibutton(app.GridLayout14, 'push');
            app.ResetColFilt.ButtonPushedFcn = createCallbackFcn(app, @ResetColFiltButtonPushed, true);
            app.ResetColFilt.Icon = 'symbols_1_Reset Col. Filter.png';
            app.ResetColFilt.Tooltip = {'Reset Column Filter'};
            app.ResetColFilt.Text = '';
            app.AddColFilt = uibutton(app.GridLayout14, 'push');
            app.AddColFilt.ButtonPushedFcn = createCallbackFcn(app, @AddColFiltButtonPushed, true);
            app.AddColFilt.Icon = 'symbols_1_Col Filter.png';
            app.AddColFilt.Tooltip = {'Add Column Filter'};
            app.AddColFilt.Text = '';
            app.ShowfulltableCheckBox = uicheckbox(app.GridLayout14);
            app.ShowfulltableCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowfulltableCheckBoxValueChanged, true);
            app.ShowfulltableCheckBox.Text = 'Show full table';
            app.DeleteColumn = uibutton(app.GridLayout14, 'push');
            app.DeleteColumn.ButtonPushedFcn = createCallbackFcn(app, @DeleteColumnButtonPushed, true);
            app.DeleteColumn.Icon = 'symbols_1_Delete Column-33.png';
            app.DeleteColumn.Tooltip = {'Delete Column from Database Table'};
            app.DeleteColumn.Text = '';
            app.DeleteSpectraButton = uibutton(app.GridLayout14, 'push');
            app.DeleteSpectraButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteSpectraButtonPushed, true);
            app.DeleteSpectraButton.Icon = 'Delete.png';
            app.DeleteSpectraButton.Tooltip = {'Delete Spectra from Database'};
            app.DeleteSpectraButton.Text = '';
            app.SearchEditField = uieditfield(app.GridLayout14, 'text');
            app.SearchEditField.Visible = 'off';
            app.SearchButton = uibutton(app.GridLayout14, 'push');
            app.SearchButton.Visible = 'off';
            app.SearchButton.Text = 'Search';
            app.DataSelectionTab = uitab(app.TabGroup);
            app.DataSelectionTab.Title = '2) Data Selection';
            app.GridLayout4 = uigridlayout(app.DataSelectionTab);
            app.ClassTable = uitable(app.GridLayout4);
            app.ClassTable.ColumnName = {'Active'; 'Name'; 'Value'; 'n'};
            app.ClassTable.RowName = {};
            app.ClassTable.ColumnSortable = [true true true false];
            app.ClassTable.ColumnEditable = [true true false];
            app.MethodDropDownLabel = uilabel(app.GridLayout4);
            app.MethodDropDownLabel.Text = 'Method';
            app.MethodDropDown = uidropdown(app.GridLayout4);
            app.MethodDropDown.Items = {'Unique String'};
            app.MethodDropDown.ValueChangedFcn = createCallbackFcn(app, @MethodDropDownValueChanged, true);
            app.MethodDropDown.Value = 'Unique String';
            app.VariableDropDown = uidropdown(app.GridLayout4);
            app.VariableDropDown.DropDownOpeningFcn = createCallbackFcn(app, @VariableDropDownOpening, true);
            app.VariableDropDown.ValueChangedFcn = createCallbackFcn(app, @VariableDropDownValueChanged, true);
            app.VariableDropDown.Visible = 'off';
            app.MaskSelectionButton = uibutton(app.GridLayout4, 'push');
            app.MaskSelectionButton.ButtonPushedFcn = createCallbackFcn(app, @MaskSelectionButtonPushed, true);
            app.MaskSelectionButton.Text = ' Mask Selection';
            app.ResetMaskButton = uibutton(app.GridLayout4, 'push');
            app.ResetMaskButton.ButtonPushedFcn = createCallbackFcn(app, @ResetMaskButtonPushed, true);
            app.ResetMaskButton.Text = 'Reset Mask';
            app.SelectAllButton_2 = uibutton(app.GridLayout4, 'push');
            app.SelectAllButton_2.ButtonPushedFcn = createCallbackFcn(app, @SelectAllButton_2Pushed, true);
            app.SelectAllButton_2.Text = 'Select All';
            app.UnmaskButton = uibutton(app.GridLayout4, 'push');
            app.UnmaskButton.ButtonPushedFcn = createCallbackFcn(app, @UnmaskButtonPushed, true);
            app.UnmaskButton.Text = 'Unmask';
            app.ExcludeEmptyCheckBox = uicheckbox(app.GridLayout4);
            app.ExcludeEmptyCheckBox.ValueChangedFcn = createCallbackFcn(app, @ExcludeEmptyCheckBoxValueChanged, true);
            app.ExcludeEmptyCheckBox.Text = 'Exclude Empty';
            app.HistogramFilterButton = uibutton(app.GridLayout4, 'push');
            app.HistogramFilterButton.ButtonPushedFcn = createCallbackFcn(app, @HistogramFilterButtonPushed, true);
            app.HistogramFilterButton.Text = 'HistogramFilter';
            app.PlotDataStorageTab = uitab(app.TabGroup);
            app.PlotDataStorageTab.Title = '3) PlotDataStorage';
            app.GridLayout5 = uigridlayout(app.PlotDataStorageTab);
            app.SymbolDropDownLabel = uilabel(app.GridLayout5);
            app.SymbolDropDownLabel.Text = 'Symbol';
            app.SymbolDropDown = uidropdown(app.GridLayout5);
            app.SymbolDropDown.Items = {'Circle', 'Plus sign', 'Asterisk', 'Point', 'Cross', 'Horizontal line', 'Vertical line', 'Square', 'Diamond', 'Upward-pointing triangle', 'Downward-pointing triangle', 'Right-pointing triangle', 'Left-pointing triangle', 'Pentagram', 'Hexagram'};
            app.SymbolDropDown.ItemsData = {'o', '+', '*', '.', 'x', '_', '|', 's', 'd', '^', 'v', '>', '<', 'p', 'h', 'none'};
            app.SymbolDropDown.ValueChangedFcn = createCallbackFcn(app, @SymbolDropDownValueChanged, true);
            app.SymbolDropDown.Value = 'o';
            app.PlotDataCheckBox = uicheckbox(app.GridLayout5);
            app.PlotDataCheckBox.Visible = 'off';
            app.PlotDataCheckBox.Text = 'Plot Linear';
            app.PlotDataCheckBox.Value = true;
            app.PlotLogCheckBox = uicheckbox(app.GridLayout5);
            app.PlotLogCheckBox.Visible = 'off';
            app.PlotLogCheckBox.Text = 'Plot Log';
            app.PlotCLRCheckBox = uicheckbox(app.GridLayout5);
            app.PlotCLRCheckBox.Visible = 'off';
            app.PlotCLRCheckBox.Text = 'Plot CLR';
            app.cmap_table = uitable(app.GridLayout5);
            app.cmap_table.ColumnName = {'Active'; 'ClassNames'; 'Value'; 'MarkerFaceColor'; 'n'};
            app.cmap_table.RowName = {};
            app.cmap_table.ColumnSortable = [true true false false true];
            app.cmap_table.ColumnEditable = [true true false false false];
            app.ColormapDropDownLabel = uilabel(app.GridLayout5);
            app.ColormapDropDownLabel.Text = 'Colormap';
            app.Options_ColormapDropDown = uidropdown(app.GridLayout5);
            app.Options_ColormapDropDown.Items = {'parula', 'turbo', 'hsv', 'hot', 'cool', 'spring', 'summer', 'autumn', 'winter', 'gray', 'bone', 'copper', 'pink', 'jet', 'lines', 'colorcube', 'prism', 'flag', 'white', 'ABC', 'custom'};
            app.Options_ColormapDropDown.ValueChangedFcn = createCallbackFcn(app, @Options_ColormapDropDownValueChanged, true);
            app.Options_ColormapDropDown.Value = 'parula';
            app.ColormapPreviewCheckBox = uicheckbox(app.GridLayout5);
            app.ColormapPreviewCheckBox.ValueChangedFcn = createCallbackFcn(app, @ColormapPreviewCheckBoxValueChanged, true);
            app.ColormapPreviewCheckBox.Text = 'Colormap Preview';
            app.StoreDataButton = uibutton(app.GridLayout5, 'push');
            app.StoreDataButton.ButtonPushedFcn = createCallbackFcn(app, @StoreDataButtonPushed, true);
            app.StoreDataButton.Text = 'Store Data';
            app.StoreClassMeanButton = uibutton(app.GridLayout5, 'push');
            app.StoreClassMeanButton.ButtonPushedFcn = createCallbackFcn(app, @StoreClassMeanButtonPushed, true);
            app.StoreClassMeanButton.Text = 'Store Class Mean';
            app.StoreClassMedianButton = uibutton(app.GridLayout5, 'push');
            app.StoreClassMedianButton.ButtonPushedFcn = createCallbackFcn(app, @StoreClassMedianButtonPushed, true);
            app.StoreClassMedianButton.Text = 'Store Class Median';
            app.InvertColormapCheckBox = uicheckbox(app.GridLayout5);
            app.InvertColormapCheckBox.ValueChangedFcn = createCallbackFcn(app, @ColormapPreviewCheckBoxValueChanged, true);
            app.InvertColormapCheckBox.Text = 'Invert Colormap';
            app.MarkerSizeEditField = uieditfield(app.GridLayout5, 'numeric');
            app.MarkerSizeEditField.Value = 10;
            app.PlotsqrtCheckBox = uicheckbox(app.GridLayout5);
            app.PlotsqrtCheckBox.Visible = 'off';
            app.PlotsqrtCheckBox.Text = 'Plot Sqrt';
            app.PlotSectionTab = uitab(app.TabGroup);
            app.PlotSectionTab.Title = '4) PlotSection';
            app.GridLayout7 = uigridlayout(app.PlotSectionTab);
            app.MultiCor_table = uitable(app.GridLayout7);
            app.MultiCor_table.ColumnName = {'Active'; 'Group'; 'Name'; 'Symbol'; 'SymbolSize'; 'MarkerFaceColor'; 'n'};
            app.MultiCor_table.RowName = {};
            app.MultiCor_table.ColumnSortable = [true true true true true false true];
            app.MultiCor_table.ColumnEditable = [true false true true true false false];
            app.MultiCor_table.CellEditCallback = createCallbackFcn(app, @MultiCor_tableCellEdit, true);
            app.MultiCor_table.ButtonDownFcn = createCallbackFcn(app, @MultiCor_tableButtonDown, true);
            app.Tree_MultiCor_VarName = uitree(app.GridLayout7, 'checkbox');
            app.Tree_MultiCor_Groups = uitree(app.GridLayout7, 'checkbox');
            % Assign Checked Nodes
            app.Tree_MultiCor_Groups.CheckedNodesChangedFcn = createCallbackFcn(app, @Tree_MultiCor_GroupsCheckedNodesChanged, true);
            app.RemoveGroupButton = uibutton(app.GridLayout7, 'push');
            app.RemoveGroupButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveGroupButtonPushed, true);
            app.RemoveGroupButton.Text = 'Remove Group';
            app.ClearAllButton = uibutton(app.GridLayout7, 'push');
            app.ClearAllButton.ButtonPushedFcn = createCallbackFcn(app, @ClearAllButtonPushed, true);
            app.ClearAllButton.Text = 'Clear All';
            app.moveupButton = uibutton(app.GridLayout7, 'push');
            app.moveupButton.ButtonPushedFcn = createCallbackFcn(app, @moveupButtonPushed, true);
            app.moveupButton.Text = 'move up';
            app.movedownButton = uibutton(app.GridLayout7, 'push');
            app.movedownButton.ButtonPushedFcn = createCallbackFcn(app, @movedownButtonPushed, true);
            app.movedownButton.Text = 'move down';
            app.ActiveSetSpinnerLabel = uilabel(app.GridLayout7);
            app.ActiveSetSpinnerLabel.Visible = 'off';
            app.ActiveSetSpinnerLabel.Text = 'Active Set';
            app.ActiveSetSpinner = uispinner(app.GridLayout7);
            app.ActiveSetSpinner.RoundFractionalValues = 'on';
            app.ActiveSetSpinner.ValueChangedFcn = createCallbackFcn(app, @ActiveSetSpinnerValueChanged, true);
            app.ActiveSetSpinner.Visible = 'off';
            app.ColorPreviewCheckBox = uicheckbox(app.GridLayout7);
            app.ColorPreviewCheckBox.ValueChangedFcn = createCallbackFcn(app, @ColorPreviewCheckBoxValueChanged, true);
            app.ColorPreviewCheckBox.Text = 'Color Preview';
            app.TabGroup3 = uitabgroup(app.GridLayout7);
            app.MinPlotXFiguresTab = uitab(app.TabGroup3);
            app.MinPlotXFiguresTab.Title = 'MinPlotX Figures';
            app.GridLayout17 = uigridlayout(app.MinPlotXFiguresTab);
            app.Tree2 = uitree(app.GridLayout17, 'checkbox');
            app.PlotSelectedButton = uibutton(app.GridLayout17, 'push');
            app.PlotSelectedButton.ButtonPushedFcn = createCallbackFcn(app, @PlotSelectedButtonPushed, true);
            app.PlotSelectedButton.Text = 'Plot Selected';
            app.ScatterPlotsTab = uitab(app.TabGroup3);
            app.ScatterPlotsTab.Title = 'ScatterPlots';
            app.GridLayout10 = uigridlayout(app.ScatterPlotsTab);
            app.NewScatterPlotButton = uibutton(app.GridLayout10, 'push');
            app.NewScatterPlotButton.ButtonPushedFcn = createCallbackFcn(app, @NewScatterPlotButtonPushed, true);
            app.NewScatterPlotButton.Text = 'New Scatter Plot';
            app.PlotsqrtCheckBox_2 = uicheckbox(app.GridLayout10);
            app.PlotsqrtCheckBox_2.Text = 'Plot Sqrt';
            app.ExcludeEmptyCheckBox_2 = uicheckbox(app.GridLayout10);
            app.ExcludeEmptyCheckBox_2.Text = 'Exclude Empty';
            app.ExcludeEmptyCheckBox_2.Value = true;
            app.LinkAxesCheckBox = uicheckbox(app.GridLayout10);
            app.LinkAxesCheckBox.Text = 'Link Axes';
            app.PlotCLRCheckBox_2 = uicheckbox(app.GridLayout10);
            app.PlotCLRCheckBox_2.Text = 'Plot CLR';
            app.PlotLogCheckBox_2 = uicheckbox(app.GridLayout10);
            app.PlotLogCheckBox_2.Text = 'Plot Log';
            app.PlotDataCheckBox_2 = uicheckbox(app.GridLayout10);
            app.PlotDataCheckBox_2.Text = 'Plot Data';
            app.PlotDataCheckBox_2.Value = true;
            app.TernPlotTab = uitab(app.TabGroup3);
            app.TernPlotTab.Title = 'TernPlot';
            app.GridLayout11 = uigridlayout(app.TernPlotTab);
            app.NewTernPlotButton = uibutton(app.GridLayout11, 'push');
            app.NewTernPlotButton.ButtonPushedFcn = createCallbackFcn(app, @NewTernPlotButtonPushed, true);
            app.NewTernPlotButton.Text = 'New Tern Plot';
            app.PlotLogCheckBox_3 = uicheckbox(app.GridLayout11);
            app.PlotLogCheckBox_3.Text = 'Plot Log';
            app.PlotLogCheckBox_3.Value = true;
            app.PlotDataCheckBox_3 = uicheckbox(app.GridLayout11);
            app.PlotDataCheckBox_3.Text = 'Plot Data';
            app.MaxSubpltSpinnerLabel = uilabel(app.GridLayout11);
            app.MaxSubpltSpinnerLabel.Text = 'Max Subplt';
            app.MaxSubpltSpinner = uispinner(app.GridLayout11);
            app.MaxSubpltSpinner.Limits = [1 100];
            app.MaxSubpltSpinner.RoundFractionalValues = 'on';
            app.MaxSubpltSpinner.Value = 16;
            app.PlotsqrtCheckBox_3 = uicheckbox(app.GridLayout11);
            app.PlotsqrtCheckBox_3.Text = 'Plot Sqrt';
            app.PlotCLRCheckBox_3 = uicheckbox(app.GridLayout11);
            app.PlotCLRCheckBox_3.Text = 'Plot CLR';
            app.Norm_CheckBox_2 = uicheckbox(app.GridLayout11);
            app.Norm_CheckBox_2.Text = 'Normalize';
            app.AllSettingsCheckBox = uicheckbox(app.GridLayout7);
            app.AllSettingsCheckBox.ValueChangedFcn = createCallbackFcn(app, @ColorPreviewCheckBoxValueChanged, true);
            app.AllSettingsCheckBox.Text = 'All Settings';
            app.ReadStyleButton = uibutton(app.GridLayout7, 'push');
            app.ReadStyleButton.ButtonPushedFcn = createCallbackFcn(app, @ReadStyleButtonPushed, true);
            app.ReadStyleButton.Visible = 'off';
            app.ReadStyleButton.Text = 'Read Style';
            app.ExportStyleButton = uibutton(app.GridLayout7, 'push');
            app.ExportStyleButton.ButtonPushedFcn = createCallbackFcn(app, @ExportStyleButtonPushed, true);
            app.ExportStyleButton.Visible = 'off';
            app.ExportStyleButton.Text = 'Export Style';
            app.Full_TTab = uitab(app.TabGroup);
            app.Full_TTab.Title = 'Full_T';
            app.GridLayout18 = uigridlayout(app.Full_TTab);
            app.Full_T = uitable(app.GridLayout18);
            app.Full_T.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.Full_T.RowName = {};
            app.Full_T.Visible = 'off';
            app.ShowFull_TCheckBox = uicheckbox(app.GridLayout18);
            app.ShowFull_TCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowFull_TCheckBoxValueChanged, true);
            app.ShowFull_TCheckBox.Text = 'Show Full_T';
            app.Tree = uitree(app.GridLayout16, 'checkbox');
            app.Tree.Editable = 'on';
            % Assign Checked Nodes
            app.Tree.CheckedNodesChangedFcn = createCallbackFcn(app, @TreeCheckedNodesChanged, true);
            app.SelectAllButton = uibutton(app.GridLayout16, 'push');
            app.SelectAllButton.ButtonPushedFcn = createCallbackFcn(app, @SelectAllButtonPushed, true);
            app.SelectAllButton.Text = 'Select All';
            app.DeselectAllButton = uibutton(app.GridLayout16, 'push');
            app.DeselectAllButton.ButtonPushedFcn = createCallbackFcn(app, @DeselectAllButtonPushed, true);
            app.DeselectAllButton.Text = 'Deselect All';
            app.ResetMaskButton_2 = uibutton(app.GridLayout16, 'push');
            app.ResetMaskButton_2.ButtonPushedFcn = createCallbackFcn(app, @ResetMaskButton_2Pushed, true);
            app.ResetMaskButton_2.Text = 'Reset Mask';
            app.MaskButton = uibutton(app.GridLayout16, 'push');
            app.MaskButton.ButtonPushedFcn = createCallbackFcn(app, @MaskButtonPushed, true);
            app.MaskButton.Text = ' Mask';
            app.NumClassesPanel = uipanel(app.AdvancedPlotModuleGUI);
            app.NumClassesPanel.Title = 'Num Classes';
            app.NumClassesPanel.Visible = 'off';
            app.GridLayout3 = uigridlayout(app.NumClassesPanel);
            app.UIAxes = uiaxes(app.GridLayout3);
            xlabel(app.UIAxes, 'n Classes')
            ylabel(app.UIAxes, 'sumd')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.Visible = 'off';
            app.UIAxes2 = uiaxes(app.GridLayout3);
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.RandomSeedSpinner = uispinner(app.GridLayout3);
            app.RandomSeedSpinner.Limits = [1 Inf];
            app.RandomSeedSpinner.RoundFractionalValues = 'on';
            app.RandomSeedSpinner.Value = 1;
            app.RandomSeedSpinnerLabel = uilabel(app.GridLayout3);
            app.RandomSeedSpinnerLabel.Text = 'Random Seed';
            app.IterationsSpinner = uispinner(app.GridLayout3);
            app.IterationsSpinner.Limits = [1 Inf];
            app.IterationsSpinner.RoundFractionalValues = 'on';
            app.IterationsSpinner.Value = 5;
            app.IterationsSpinnerLabel = uilabel(app.GridLayout3);
            app.IterationsSpinnerLabel.Text = 'Iterations';
            app.RunClassTestButton = uibutton(app.GridLayout3, 'push');
            app.RunClassTestButton.Text = 'Run Class Test';
            app.Norm_CheckBox = uicheckbox(app.GridLayout3);
            app.Norm_CheckBox.Text = 'Normalize';
            app.Norm_CheckBox.Value = true;
            app.CLR_CheckBox = uicheckbox(app.GridLayout3);
            app.CLR_CheckBox.Text = 'CLR';
            app.MinClassesSpinnerLabel = uilabel(app.GridLayout3);
            app.MinClassesSpinnerLabel.Text = 'Min # Classes';
            app.MinClassesSpinner = uispinner(app.GridLayout3);
            app.MinClassesSpinner.Limits = [2 Inf];
            app.MinClassesSpinner.RoundFractionalValues = 'on';
            app.MinClassesSpinner.Value = 2;
            app.MaxClassesSpinnerLabel = uilabel(app.GridLayout3);
            app.MaxClassesSpinnerLabel.Text = 'Max # Classes';
            app.MaxClassesSpinner = uispinner(app.GridLayout3);
            app.MaxClassesSpinner.Limits = [2 Inf];
            app.MaxClassesSpinner.RoundFractionalValues = 'on';
            app.MaxClassesSpinner.Value = 15;
            app.ClassesSpinnerLabel = uilabel(app.GridLayout3);
            app.ClassesSpinnerLabel.Text = '# Classes';
            app.ClassesSpinner = uispinner(app.GridLayout3);
            app.ClassesSpinner.Limits = [2 Inf];
            app.ClassesSpinner.RoundFractionalValues = 'on';
            app.ClassesSpinner.Value = 10;
            app.SortClassesDropDownLabel = uilabel(app.GridLayout3);
            app.SortClassesDropDownLabel.Text = 'Sort Classes';
            app.SortClassesDropDown = uidropdown(app.GridLayout3);
            app.SortClassesDropDown.Items = {'no sort', 'C Distance', 'Integration', 'n Pixel'};
            app.SortClassesDropDown.Value = 'Integration';
            app.Add2TableButton = uibutton(app.GridLayout3, 'push');
            app.Add2TableButton.Text = 'Add2Table';
            app.DeselectAllButton_2 = uibutton(app.GridLayout3, 'push');
            app.DeselectAllButton_2.Text = 'Deselect All';
            app.SelectAllButton_3 = uibutton(app.GridLayout3, 'push');
            app.SelectAllButton_3.Text = 'Select All';
            app.sqrt_CheckBox = uicheckbox(app.GridLayout3);
            app.sqrt_CheckBox.Text = 'Sqrt';
            app.MaskClassButton = uibutton(app.GridLayout3, 'push');
            app.MaskClassButton.Text = 'Mask Class';
            app.LogCheckBox = uicheckbox(app.GridLayout3);
            app.LogCheckBox.Text = 'Log';
            app.SelectButton = uibutton(app.GridLayout3, 'push');
            app.SelectButton.Text = 'Select';
            app.TabGroup2 = uitabgroup(app.GridLayout3);
            app.VarSelectionTab = uitab(app.TabGroup2);
            app.VarSelectionTab.Title = 'VarSelection';
            app.GridLayout8 = uigridlayout(app.VarSelectionTab);
            app.Tree_2 = uitree(app.GridLayout8, 'checkbox');
            app.ResultTab = uitab(app.TabGroup2);
            app.ResultTab.Title = 'Result';
            app.GridLayout9 = uigridlayout(app.ResultTab);
            app.ClassTree = uitree(app.GridLayout9, 'checkbox');
            app.ClassTree.Editable = 'on';
            app.TSNETestButton = uibutton(app.AdvancedPlotModuleGUI, 'push');
            app.TSNETestButton.ButtonPushedFcn = createCallbackFcn(app, @TSNETestButtonPushed, true);
            app.TSNETestButton.Visible = 'off';
            app.TSNETestButton.Text = 'TSNE Test';
            app.ContextMenu = uicontextmenu(app.AdvancedPlotModuleGUI);
            app.SaveMenu = uimenu(app.ContextMenu);
            app.SaveMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveMenuSelected, true);
            app.SaveMenu.Text = 'Save';
            app.LoadMenu = uimenu(app.ContextMenu);
            app.LoadMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadMenuSelected, true);
            app.LoadMenu.Text = 'Load';
            % Assign app.ContextMenu
            app.Tree_MultiCor_Groups.ContextMenu = app.ContextMenu;
            % Show the figure after all components are created
            app.AdvancedPlotModuleGUI.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AdvancedPlotModule(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.AdvancedPlotModuleGUI)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.AdvancedPlotModuleGUI)
        end
    end
end