classdef Import_Module_old < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Import_ModuleFigure           matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        TabGroup                      matlab.ui.container.TabGroup
        Input                         matlab.ui.container.Tab
        GridLayout2                   matlab.ui.container.GridLayout
        Label_2                       matlab.ui.control.Label
        ThresholdLabel                matlab.ui.control.Label
        eIdLabel                      matlab.ui.control.Label
        lasertype                     matlab.ui.control.DropDown
        SetThresholdEditField         matlab.ui.control.NumericEditField
        UIAxes_listbox                matlab.ui.control.ListBox
        eId_textfield                 matlab.ui.control.NumericEditField
        logfilepath_textfield         matlab.ui.control.EditField
        ResolutionlogfileLabel        matlab.ui.control.Label
        agilentcsvpath_textfield      matlab.ui.control.EditField
        AgilentcsvLabel               matlab.ui.control.Label
        infos_textarea                matlab.ui.control.TextArea
        start_button                  matlab.ui.control.Button
        logfilepath_button            matlab.ui.control.Button
        agilentcsvpath_button         matlab.ui.control.Button
        UIAxes                        matlab.ui.control.UIAxes
        Inspection                    matlab.ui.container.Tab
        GridLayout3                   matlab.ui.container.GridLayout
        zscore_textfield              matlab.ui.control.NumericEditField
        zscorethresholdLabel          matlab.ui.control.Label
        spotname_dropdown             matlab.ui.control.DropDown
        SpotNameDropDownLabel         matlab.ui.control.Label
        elements_listbox              matlab.ui.control.ListBox
        log_checkbox                  matlab.ui.control.CheckBox
        nextsignal_button             matlab.ui.control.Button
        previoussignal_button         matlab.ui.control.Button
        signal_axes                   matlab.ui.control.UIAxes
        visual_selection              matlab.ui.container.Tab
        GridLayout4                   matlab.ui.container.GridLayout
        enable_zoom_button            matlab.ui.control.Button
        draw_rectangle_button         matlab.ui.control.Button
        importsignals_button          matlab.ui.control.Button
        SpotnameEditField             matlab.ui.control.EditField
        SpotnameEditFieldLabel        matlab.ui.control.Label
        outputpath_vis_button         matlab.ui.control.Button
        outputpath_vis_textfield      matlab.ui.control.EditField
        Label                         matlab.ui.control.Label
        agilentcsvpath_vis_button     matlab.ui.control.Button
        CutSaveButton                 matlab.ui.control.Button
        agilentcsvpath_vis_textfield  matlab.ui.control.EditField
        EditFieldLabel                matlab.ui.control.Label
        allsignals_axes               matlab.ui.control.UIAxes
    end


    properties (Access = private)
        % initialize variables that are used within the app
        eId=1
        selected_signal
        selected_elements
        selected_elements_allsignals
        threshold
        active_table
        table_oldlaser
        all_signals
        line
        rect
        xDataInRect
        yDataInRect
        single_signal
        outputfolder

        LOGDAT % Description
        SIGDAT % Description

        
    end

    properties (Access = public)
        % variables initialized here can also be accessed from outside
        % e.g. LASA_app=LASA in command window and then access data
        data
        icp_signals_processed
        LOGF
        icp_signals_raw

        A
        SMAN
        STD
        UNK
        SRM

        SILLS

    end

    methods (Access = private)

        function update_gui_fun(app)
            % this function updates the GUI where needed
            %here it updates the spotname_dropdown
            app.spotname_dropdown.Items=app.data.measurement(app.eId).spotnames;
            app.spotname_dropdown.ItemsData=1:numel(app.data.measurement(app.eId).spotnames);

            update_listbox_fun(app)
            update_fig_fun(app)
            update_UIAxes_listbox_fun(app)
        end

        function update_listbox_fun(app)
            % this function updates the elements_listbox when needed

            app.selected_signal = app.spotname_dropdown.Value;
            update_active_table_fun(app)

            % this function updates the elements_listbox when needed
            el_names = app.active_table.Properties.VariableNames; %read out the names of the elements from each signal
            app.elements_listbox.Items = el_names; %fills up the elements_listbox with the names
            app.elements_listbox.ItemsData=1:numel(el_names);

        end


        %makes the copy of an input table sliced_signals.signal and sums up
        function update_active_table_fun(app)

            T=app.data.measurement(app.eId).sliced_signals(app.selected_signal).signal;

            T.totalcounts = sum(table2array(T(:,2:end)),2,'omitnan');
            app.active_table=T;
        end


        function update_fig_fun(app)
            %this function updates the figure
            cla(app.signal_axes) %clear plot

            if isempty(app.selected_elements) %if it is empty, do nothing
                return
            end

            % if there is something try plotting it, active table is
            % defined further down, basically makes a copy of the
            % sliced_signal.signal table and adds the totalcounts

            try
                x=app.active_table.Time_Sec_;
                y=table2array(app.active_table(:,app.selected_elements));

                plot(app.signal_axes,x,y,LineWidth=2)
                % if the checkbox is checked plot in log or linear scale
                if app.log_checkbox.Value==true
                    app.signal_axes.YScale="log";
                else
                    app.signal_axes.YScale="linear";
                end

                % if something goes wrong give the message via the
                % log_out_fun function
            catch ME
                log_out_fun(app,ME.message)
                % log_out_fun(app,ME.stack.line)
                %      log_out_fun(app,ME)
            end

        end


        function update_UIAxes_listbox_fun(app)
            % this function updates the elements_listbox when needed

            el_names = app.icp_signals_processed(:, 2:end).Properties.VariableNames; %read out the names of the elements from each signal

            app.UIAxes_listbox.Items = el_names; %fills up the elements_listbox with the names
            app.UIAxes_listbox.ItemsData = 2:numel(el_names);

        end

        function update_UIAxes_fig_fun(app)

                x=app.icp_signals_processed.Time_Sec_;
                y=table2array(app.icp_signals_processed(:,app.selected_elements_allsignals));

                plot(app.UIAxes,x, y, LineWidth=2)

                %plot(app.UIAxes, app.icp_signals_raw.(1), table2array(app.icp_signals_raw(:,2:end)))
                %app.UIAxes.XLim=[min(app.icp_signals_raw.(1)), max(app.icp_signals_raw.(1))];

            end


        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        %% Visual Selection

        function plot_allsignals(app)

            y_axis = app.data.measurement(app.eId).all_signals.totalcounts;

            % Plot the data
            app.line = plot(app.allsignals_axes, y_axis, 'k', 'LineWidth', 2);
            app.allsignals_axes.YScale = "log";
            ylim(app.allsignals_axes, [min(y_axis), inf]);
            xlabel(app.allsignals_axes, 'Sweeps');
            ylabel(app.allsignals_axes, 'Total Counts per second');


        end


        function data_selector(app)

            % rectangle i need to write this as a function and return the x and y data in the rectangle from the function
            %this x and y data is then passed to a output function which is
            %activated by the other button and outputs the selected data as
            %a table with the name given in the textfield

            % Draw a rectangle on the plot
            app.rect = drawrectangle(app.allsignals_axes, 'Color', [0.5 0.5 0.5], 'LineWidth', 0.5);

            % Get the position and limints of the rectangle
            pos = app.rect.Position;
            xLimits = [pos(1), pos(1) + pos(3)];
            yLimits = [pos(2), pos(2) + pos(4)];

            % Extract the data from the line
            xData = get(app.line, 'XData');
            yData = get(app.line, 'YData');

            % Determine which data points are within the rectangle
            inRect = xData >= xLimits(1) & xData <= xLimits(2) & yData >= yLimits(1) & yData <= yLimits(2);
            app.data.measurement(app.eId).xDataInRect = xData(inRect);
            app.data.measurement(app.eId).yDataInRect = yData(inRect);

        end


        function visual_cutter(app)

            % Get the number of columns and extract all of them except the second
            % to last one (which are the totalcounts)
            numCols = size(app.data.measurement(app.eId).all_signals, 2);
            app.data.measurement(app.eId).single_signal = app.data.measurement(app.eId).all_signals(app.data.measurement(app.eId).xDataInRect, 1:numCols-1);


        end


        function csvoutputgenerator(app)

            if isempty(app.SpotnameEditField.Value) == true
                message = ["Spotname is missing"];
                uialert(app.Import_ModuleFigure,message,"Warning", ...
                    "Icon","warning");
                return
            else
                filename = app.SpotnameEditField.Value;
            end


            filepath = fullfile(app.outputfolder, [filename, '.csv']);

            writetable(app.data.measurement(app.eId).single_signal, filepath);

            %% Add the timestamps in a txt file needed for Drift Correction %%%%
            % first get the minimum and maximum values
            starttime = min(app.data.measurement(app.eId).single_signal.Time_Sec_);
            endtime = max(app.data.measurement(app.eId).single_signal.Time_Sec_);
            % format the seconds to the right HH:MM:SS format
            startdate = datestr(seconds(starttime),'HH:MM:SS');
            enddate = datestr(seconds(endtime),'HH:MM:SS');

            % Define the .txt file path
            txtfilepath = fullfile(app.outputfolder, 'filenames_and_startdates.txt');

            % Open the .txt file with append permission
            txtfid = fopen(txtfilepath, 'a');

            % Write the filename and the startdate to the .txt file
            fprintf(txtfid, '%s, %s\n', filename, startdate);

            % Close the .txt file
            fclose(txtfid);


        end


    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)

            [app.SMAN,app.STD,app.UNK, app.A, app.SRM] = get_sills_data;
            app.A.sillspath = pwd;

            app.agilentcsvpath_textfield.Value="D:\OneDrive - Universitaet Bern\PhD\Programs\SILLS new version\Import Module\Mineral Analysis\Uncut Signals.csv";
            app.logfilepath_textfield.Value="D:\OneDrive - Universitaet Bern\PhD\Programs\SILLS new version\Import Module\Mineral Analysis\Alm06_Min2_log_20240117_145530.log";

            %
            if exist("mainapp","var")
                app.SILLS=mainapp;
            end
        end

        % Button pushed function: agilentcsvpath_button
        function agilentcsvpath_buttonPushed(app, event)
            % if the button is pushed it asks for a file with .cvs ending

            [filename, pathname] = uigetfile('*.csv', 'Select a CSV file');
            fullfilename = fullfile(pathname, filename);
            %if he asks but no file is selected but cancel is pressed it is
            %not a problem anymore (I am not sure how though...)
            if not(filename==0)

                app.agilentcsvpath_textfield.Value = fullfilename;
            end

            figure(app.Import_ModuleFigure)
        end

        % Button pushed function: logfilepath_button
        function logfilepath_buttonPushed(app, event)
            % if the button is pushed it asks for a file with .log ending
            [filename, pathname] = uigetfile('*.log', 'Select a log file');
            fullfilename = fullfile(pathname, filename);
            %if he asks but no file is selected but cancel is pressed it is
            %not a problem anymore (I am not sure how though...)
            if not(filename==0)

                app.logfilepath_textfield.Value = fullfilename;
            end

            figure(app.Import_ModuleFigure)
        end

        % Callback function
        function outputpath_buttonPushed(app, event)
            % if the button is pushed it asks for a folder to export the
            % csv files of the signals into
            pathname = uigetdir;
            %if he asks but no file is selected but cancel is pressed it is
            %not a problem anymore (I am not sure how though...)
            if not(pathname==0)

                app.outputpath_textfield.Value = pathname;
            end

            figure(app.Import_ModuleFigure)
        end

        % Button pushed function: start_button
        function start_buttonPushed(app, event)
            if strcmp(app.start_button.Text, 'Import Signals')
                logfilePath = app.logfilepath_textfield.Value;
                csvinputpath = app.agilentcsvpath_textfield.Value;

                % reads in the data from the logfile
                app.LOGDAT = extract_logfile_data(logfilePath);

                % just cuts the signals
                [app.SIGDAT, app.icp_signals_processed, app.icp_signals_raw] = cut_signals(csvinputpath);

                % this sets the background and signal intervals
                %output of cut_signals as input
                % eventually this has to work on UNK and STD and not app.SIGDAT (its the same
                % anyways)
                app.SIGDAT  = set_automatic_intervals(app.SIGDAT);

                % this gives the HH:MM information for the drift correction, takes the output of
                %output of cut_signals as input
                % eventually this has to work on UNK and STD and not app.SIGDAT (its the same
                % anyways)
                app.SIGDAT = set_automatic_drifttime(app.SIGDAT);

                %% ==============================================================================================%%
                %Independent of the input, when the data arrives here it should all be the same
                %%==============================================================================================%%

                % Distribute the data from app.SIGDAT to STD and UNK
                [app.STD, app.UNK] = SIGDAT_to_STD_UNK(app.SIGDAT, app.LOGDAT);

                % Fill the A struct with infos from STD and UNK
                [app.A] = STD_UNK_to_A(app.STD, app.UNK);


                %% make the overview plot
                plot(app.UIAxes, app.icp_signals_processed.Time_Sec_, table2array(app.icp_signals_processed(:,2:end)))
                app.UIAxes.XLim=[min(app.icp_signals_processed.Time_Sec_), max(app.icp_signals_processed.Time_Sec_)];

                app.UIAxes.YScale = "log";
                xlabel(app.UIAxes, 'Sweeps');
                ylabel(app.UIAxes, 'Total Counts per second');


                update_UIAxes_listbox_fun(app)

                app.start_button.Text = 'Send to SILLS';

            else
                sillsapp = app.SILLS;
                public_import_fullSTD(sillsapp,app.STD,true)
                public_import_fullUNK(sillsapp,app.UNK,true)
                public_import_fullA(sillsapp,app.A)
                figure(app.SILLS.h_fig)
                delete(app)
            end


        end

        % Value changed function: spotname_dropdown
        function spotname_dropdownValueChanged(app, event)
            % the spotname_dropdown no changes the selected_signal

            app.selected_signal = app.spotname_dropdown.Value;
            %update the listbox of the elements and the figure
            update_listbox_fun(app)

            update_fig_fun(app)
        end

        % Value changed function: elements_listbox
        function elements_listboxValueChanged(app, event)

            app.selected_elements = app.elements_listbox.Value;

            update_fig_fun(app)
        end

        % Value changed function: log_checkbox
        function logCheckBoxValueChanged(app, event)
            % updates the plot to logarithimic if it is checked
            value = app.log_checkbox.Value;
            update_fig_fun(app)
        end

        % Button pushed function: previoussignal_button
        function previoussignal_buttonPushed(app, event)
            %check if this is the first selected_signal, if yes and you
            %press back it jumps to the last signal, otherwise it just goes
            %one signal back
            if app.selected_signal==1

                app.selected_signal=numel(app.spotname_dropdown.Items);
                app.spotname_dropdown.Value=numel(app.spotname_dropdown.Items);

            else
                app.selected_signal=app.selected_signal-1;
                app.spotname_dropdown.Value=app.spotname_dropdown.Value-1;


            end
            %update the element list and the plot
            update_listbox_fun(app)

            update_fig_fun(app)
        end

        % Button pushed function: nextsignal_button
        function nextsignal_buttonPushed(app, event)
            %check if this is the first selected_signal, if yes and you
            %press it jumps to the first signal, otherwise it just goes
            %one signal forward
            if app.selected_signal==numel(app.spotname_dropdown.Items)

                app.selected_signal=1;
                app.spotname_dropdown.Value=1;


            else
                app.selected_signal=app.selected_signal+1;
                app.spotname_dropdown.Value=app.spotname_dropdown.Value+1;
            end
            %update the element list and the plot
            update_listbox_fun(app)

            update_fig_fun(app)
        end

        % Value changed function: zscore_textfield
        function zscore_textfieldValueChanged(app, event)
            %displays the backgroundvalue
            value = app.threshold.Value;

        end

        % Callback function
        function LoadProjectMenuSelected(app, event)
            %load %%%%%%%%%% Work in Progress %%%%%%%%%%%

            %update_gui_fun(app)         update   app.eId_textfield.Value=app.eId;

        end

        % Callback function
        function SaveProjectMenuSelected(app, event)
            %save %load %%%%%%%%%% Work in Progress %%%%%%%%%%%
            %"new" is also needed


        end

        % Button pushed function: agilentcsvpath_vis_button
        function agilentcsvpath_vis_buttonPushed(app, event)
            % if the button is pushed it asks for a file with .cvs ending

            [filename_oldlaser, pathname_oldlaser] = uigetfile('*.csv', 'Select a CSV file');
            fullfilename_oldlaser = fullfile(pathname_oldlaser, filename_oldlaser);
            %if he asks but no file is selected but cancel is pressed it is
            %not a problem anymore (I am not sure how though...)
            if not(filename_oldlaser==0)
                fullfilename_oldlaser = fullfile(pathname_oldlaser, filename_oldlaser);
                app.table_oldlaser = fullfilename_oldlaser;

                %writes the chosen pathname in the textfield
                app.agilentcsvpath_vis_textfield.Value = pathname_oldlaser;
            end

            figure(app.Import_ModuleFigure)
        end

        % Button pushed function: outputpath_vis_button
        function outputpath_vis_buttonPushed(app, event)
            % if the button is pushed it asks for a folder to export the
            % csv files of the signals into
            app.outputfolder = uigetdir;
            %if he asks but no file is selected but cancel is pressed it is
            %not a problem anymore (I am not sure how though...)
            if not(app.outputfolder==0)

                app.outputpath_vis_textfield.Value = app.outputfolder;
            end

            figure(app.Import_ModuleFigure)
        end

        % Button pushed function: importsignals_button
        function importsignals_buttonButtonPushed(app, event)

            %writes a message if no Inputfile has been selected but the
            %buttton is pressed
            if isempty(app.table_oldlaser)
                message = ["No Inputfile has been selected"];
                uialert(app.Import_ModuleFigure,message,"Warning", ...
                    "Icon","warning");
                return
            else
                %reads in the table and sums up the counts to toalcounts
                app.data.measurement(app.eId).all_signals = readtable(app.table_oldlaser);
                app.data.measurement(app.eId).all_signals.totalcounts = sum(table2array(app.data.measurement(app.eId).all_signals(:,2:end)),2,'omitnan');
                % calls the plot function defined above and plots the data
                plot_allsignals(app)
            end


        end

        % Button pushed function: enable_zoom_button
        function enable_zoom_buttonButtonPushed(app, event)
            %enables zoom
            zoom(app.allsignals_axes, 'on');

        end

        % Button pushed function: draw_rectangle_button
        function draw_rectangle_buttonButtonPushed(app, event)
            %first disables zoom and panning
            zoom(app.allsignals_axes, 'off');
            pan(app.allsignals_axes, 'off');
            %deletes the already existing rectangle if there is one
            delete(app.rect);
            %calls the
            data_selector(app)


        end

        % Button pushed function: CutSaveButton
        function CutSaveButtonPushed(app, event)


            visual_cutter(app)
            csvoutputgenerator(app)


        end

        % Value changed function: SpotnameEditField
        function SpotnameEditFieldValueChanged(app, event)
            value = app.SpotnameEditField.Value;

        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)
            
        end

        % Value changed function: UIAxes_listbox
        function UIAxes_listboxValueChanged(app, event)
            
            app.selected_elements_allsignals = app.UIAxes_listbox.Value;

            update_UIAxes_fig_fun(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Import_ModuleFigure = uifigure('Visible', 'off');
            app.Import_ModuleFigure.Name = 'LASA';
            app.GridLayout = uigridlayout(app.Import_ModuleFigure);
            app.TabGroup = uitabgroup(app.GridLayout);
            app.Input = uitab(app.TabGroup);
            app.Input.Title = 'Agilent csv + logfile';
            app.GridLayout2 = uigridlayout(app.Input);
            app.UIAxes = uiaxes(app.GridLayout2);
            xlabel(app.UIAxes, 'Time')
            ylabel(app.UIAxes, 'Counts per Second')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.agilentcsvpath_button = uibutton(app.GridLayout2, 'push');
            app.agilentcsvpath_button.ButtonPushedFcn = createCallbackFcn(app, @agilentcsvpath_buttonPushed, true);
            app.agilentcsvpath_button.Text = 'Get .csv file';
            app.logfilepath_button = uibutton(app.GridLayout2, 'push');
            app.logfilepath_button.ButtonPushedFcn = createCallbackFcn(app, @logfilepath_buttonPushed, true);
            app.logfilepath_button.Text = 'Get .log file';
            app.start_button = uibutton(app.GridLayout2, 'push');
            app.start_button.ButtonPushedFcn = createCallbackFcn(app, @start_buttonPushed, true);
            app.start_button.Text = 'Import Signals';
            app.infos_textarea = uitextarea(app.GridLayout2);
            app.AgilentcsvLabel = uilabel(app.GridLayout2);
            app.AgilentcsvLabel.Text = 'Agilent .csv file containing all signals';
            app.agilentcsvpath_textfield = uieditfield(app.GridLayout2, 'text');
            app.ResolutionlogfileLabel = uilabel(app.GridLayout2);
            app.ResolutionlogfileLabel.Text = 'Resolution .log file';
            app.logfilepath_textfield = uieditfield(app.GridLayout2, 'text');
            app.eId_textfield = uieditfield(app.GridLayout2, 'numeric');
            app.eId_textfield.Editable = 'off';
            app.UIAxes_listbox = uilistbox(app.GridLayout2);
            app.UIAxes_listbox.Multiselect = 'on';
            app.UIAxes_listbox.ValueChangedFcn = createCallbackFcn(app, @UIAxes_listboxValueChanged, true);
            app.UIAxes_listbox.Value = {'Item 1'};
            app.SetThresholdEditField = uieditfield(app.GridLayout2, 'numeric');
            app.lasertype = uidropdown(app.GridLayout2);
            app.lasertype.Items = {'new laser', 'old laser', 'no logfile'};
            app.lasertype.Enable = 'off';
            app.lasertype.Value = 'new laser';
            app.eIdLabel = uilabel(app.GridLayout2);
            app.eIdLabel.Text = 'eId';
            app.ThresholdLabel = uilabel(app.GridLayout2);
            app.ThresholdLabel.Text = 'Threshold';
            app.Label_2 = uilabel(app.GridLayout2);
            app.Label_2.Text = '';
            app.Inspection = uitab(app.TabGroup);
            app.Inspection.Title = 'New Laser Signal Inspection';
            app.GridLayout3 = uigridlayout(app.Inspection);
            app.signal_axes = uiaxes(app.GridLayout3);
            xlabel(app.signal_axes, 'Time [s]')
            ylabel(app.signal_axes, 'Counts')
            zlabel(app.signal_axes, 'Z')
            app.previoussignal_button = uibutton(app.GridLayout3, 'push');
            app.previoussignal_button.ButtonPushedFcn = createCallbackFcn(app, @previoussignal_buttonPushed, true);
            app.previoussignal_button.Text = '<';
            app.nextsignal_button = uibutton(app.GridLayout3, 'push');
            app.nextsignal_button.ButtonPushedFcn = createCallbackFcn(app, @nextsignal_buttonPushed, true);
            app.nextsignal_button.Text = '>';
            app.log_checkbox = uicheckbox(app.GridLayout3);
            app.log_checkbox.ValueChangedFcn = createCallbackFcn(app, @logCheckBoxValueChanged, true);
            app.log_checkbox.Text = 'log';
            app.log_checkbox.Value = true;
            app.elements_listbox = uilistbox(app.GridLayout3);
            app.elements_listbox.Multiselect = 'on';
            app.elements_listbox.ValueChangedFcn = createCallbackFcn(app, @elements_listboxValueChanged, true);
            app.elements_listbox.Value = {'Item 1'};
            app.SpotNameDropDownLabel = uilabel(app.GridLayout3);
            app.SpotNameDropDownLabel.Text = 'Spot Name';
            app.spotname_dropdown = uidropdown(app.GridLayout3);
            app.spotname_dropdown.Items = {'Select'};
            app.spotname_dropdown.ValueChangedFcn = createCallbackFcn(app, @spotname_dropdownValueChanged, true);
            app.spotname_dropdown.Value = 'Select';
            app.zscorethresholdLabel = uilabel(app.GridLayout3);
            app.zscorethresholdLabel.Text = 'z-score threshold';
            app.zscore_textfield = uieditfield(app.GridLayout3, 'numeric');
            app.zscore_textfield.ValueChangedFcn = createCallbackFcn(app, @zscore_textfieldValueChanged, true);
            app.zscore_textfield.Editable = 'off';
            app.visual_selection = uitab(app.TabGroup);
            app.visual_selection.Title = 'Visual Selection';
            app.GridLayout4 = uigridlayout(app.visual_selection);
            app.allsignals_axes = uiaxes(app.GridLayout4);
            xlabel(app.allsignals_axes, 'Sweeps')
            ylabel(app.allsignals_axes, 'Counts')
            zlabel(app.allsignals_axes, 'Z')
            app.EditFieldLabel = uilabel(app.GridLayout4);
            app.EditFieldLabel.Text = '';
            app.agilentcsvpath_vis_textfield = uieditfield(app.GridLayout4, 'text');
            app.CutSaveButton = uibutton(app.GridLayout4, 'push');
            app.CutSaveButton.ButtonPushedFcn = createCallbackFcn(app, @CutSaveButtonPushed, true);
            app.CutSaveButton.Text = 'Cut & Save';
            app.agilentcsvpath_vis_button = uibutton(app.GridLayout4, 'push');
            app.agilentcsvpath_vis_button.ButtonPushedFcn = createCallbackFcn(app, @agilentcsvpath_vis_buttonPushed, true);
            app.agilentcsvpath_vis_button.Text = 'Select Input';
            app.Label = uilabel(app.GridLayout4);
            app.Label.Text = '';
            app.outputpath_vis_textfield = uieditfield(app.GridLayout4, 'text');
            app.outputpath_vis_button = uibutton(app.GridLayout4, 'push');
            app.outputpath_vis_button.ButtonPushedFcn = createCallbackFcn(app, @outputpath_vis_buttonPushed, true);
            app.outputpath_vis_button.Text = 'Select Output';
            app.SpotnameEditFieldLabel = uilabel(app.GridLayout4);
            app.SpotnameEditFieldLabel.Text = 'Spotname';
            app.SpotnameEditField = uieditfield(app.GridLayout4, 'text');
            app.SpotnameEditField.ValueChangedFcn = createCallbackFcn(app, @SpotnameEditFieldValueChanged, true);
            app.importsignals_button = uibutton(app.GridLayout4, 'push');
            app.importsignals_button.ButtonPushedFcn = createCallbackFcn(app, @importsignals_buttonButtonPushed, true);
            app.importsignals_button.Text = 'Import Signals';
            app.draw_rectangle_button = uibutton(app.GridLayout4, 'push');
            app.draw_rectangle_button.ButtonPushedFcn = createCallbackFcn(app, @draw_rectangle_buttonButtonPushed, true);
            app.draw_rectangle_button.Text = 'Draw Rectangle';
            app.enable_zoom_button = uibutton(app.GridLayout4, 'push');
            app.enable_zoom_button.ButtonPushedFcn = createCallbackFcn(app, @enable_zoom_buttonButtonPushed, true);
            app.enable_zoom_button.Text = 'Enable Zoom';
            % Show the figure after all components are created
            app.Import_ModuleFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Import_Module_old(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Import_ModuleFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Import_ModuleFigure)
        end
    end
end