classdef LASA_v5_for_PySILLS < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        LASAUIFigure                  matlab.ui.Figure
        LASAMenu                      matlab.ui.container.Menu
        LoadProjectMenu               matlab.ui.container.Menu
        SaveProjectMenu               matlab.ui.container.Menu
        GridLayout                    matlab.ui.container.GridLayout
        TabGroup                      matlab.ui.container.TabGroup
        Input                         matlab.ui.container.Tab
        GridLayout2                   matlab.ui.container.GridLayout
        eId_textfield                 matlab.ui.control.NumericEditField
        eIdEditFieldLabel             matlab.ui.control.Label
        lasertype                     matlab.ui.control.DropDown
        DropDownLabel                 matlab.ui.control.Label
        outputpath_textfield          matlab.ui.control.EditField
        outEditFieldLabel             matlab.ui.control.Label
        logfilepath_textfield         matlab.ui.control.EditField
        logEditFieldLabel             matlab.ui.control.Label
        agilentcsvpath_textfield      matlab.ui.control.EditField
        inputcsvEditFieldLabel        matlab.ui.control.Label
        infos_textarea                matlab.ui.control.TextArea
        infosTextAreaLabel            matlab.ui.control.Label
        start_button                  matlab.ui.control.Button
        outputpath_button             matlab.ui.control.Button
        logfilepath_button            matlab.ui.control.Button
        agilentcsvpath_button         matlab.ui.control.Button
        Inspection                    matlab.ui.container.Tab
        GridLayout5                   matlab.ui.container.GridLayout
        Panel                         matlab.ui.container.Panel
        GridLayout3                   matlab.ui.container.GridLayout
        zscore_textfield              matlab.ui.control.NumericEditField
        zscorethresholdLabel          matlab.ui.control.Label
        spotname_dropdown             matlab.ui.control.DropDown
        SpotNameDropDownLabel         matlab.ui.control.Label
        elements_listbox              matlab.ui.control.ListBox
        log_checkbox                  matlab.ui.control.CheckBox
        nextsignal_button             matlab.ui.control.Button
        previoussignal_button         matlab.ui.control.Button
        signal_axes                   matlab.ui.control.UIAxes
        visual_selection              matlab.ui.container.Tab
        GridLayout4                   matlab.ui.container.GridLayout
        enable_zoom_button            matlab.ui.control.Button
        draw_rectangle_button         matlab.ui.control.Button
        importsignals_button          matlab.ui.control.Button
        SpotnameEditField             matlab.ui.control.EditField
        SpotnameEditFieldLabel        matlab.ui.control.Label
        outputpath_vis_button         matlab.ui.control.Button
        outputpath_vis_textfield      matlab.ui.control.EditField
        Label                         matlab.ui.control.Label
        agilentcsvpath_vis_button     matlab.ui.control.Button
        CutSaveButton                 matlab.ui.control.Button
        agilentcsvpath_vis_textfield  matlab.ui.control.EditField
        EditFieldLabel                matlab.ui.control.Label
        allsignals_axes               matlab.ui.control.UIAxes
    end


    properties (Access = private)
         % initialize variables that are used within the app
        eId=1
        selected_signal
        selected_elements
        threshold
        active_table
        table_oldlaser
        all_signals
        line
        rect
        xDataInRect
        yDataInRect
        single_signal
        outputfolder
       
    end
     
    properties (Access = public)
         % variables initialized here can also be accessed from outside
         % e.g. LASA_app=LASA in command window and then access data
        data
    end

    methods (Access = private)

        function update_gui_fun(app)
            % this function updates the GUI where needed
            %here it updates the spotname_dropdown
            app.spotname_dropdown.Items=app.data.measurement(app.eId).spotnames;
            app.spotname_dropdown.ItemsData=1:numel(app.data.measurement(app.eId).spotnames);
            
            update_listbox_fun(app)
            update_fig_fun(app)
        end

        function update_listbox_fun(app)
            % this function updates the elements_listbox when needed

            app.selected_signal = app.spotname_dropdown.Value;
            update_active_table_fun(app)

            % this function updates the elements_listbox when needed
            el_names = app.active_table.Properties.VariableNames; %read out the names of the elements from each signal
            app.elements_listbox.Items = el_names; %fills up the elements_listbox with the names
            app.elements_listbox.ItemsData=1:numel(el_names);

        end

        function update_fig_fun(app)
            %this function updates the figure
            cla(app.signal_axes) %clear plot

            if isempty(app.selected_elements) %if it is empty, do nothing
                return
            end

            % if there is something try plotting it, active table is
            % defined further down, basically makes a copy of the
            % sliced_signal.signal table and adds the totalcounts

            try
                x=app.active_table.Time_Sec_;
                y=table2array(app.active_table(:,app.selected_elements));

                plot(app.signal_axes,x,y,LineWidth=2)
                % if the checkbox is checked plot in log or linear scale
                if app.log_checkbox.Value==true
                    app.signal_axes.YScale="log";
                else
                    app.signal_axes.YScale="linear";
                end
               
                % if something goes wrong give the message via the
                % log_out_fun function
            catch ME
                log_out_fun(app,ME.message)
                % log_out_fun(app,ME.stack.line)
                %      log_out_fun(app,ME)
            end

        end

        function [pointnumbers,spotsize,spotnames,fired, measuringdate] = logfile_analyzer_newLaser(app, filepath)
            % Open the file
            logfile = fopen(filepath,'r');

            % Check if the file was opened successfully
            if logfile == -1
                error('Cannot open file')
            end

            % Define the patterns to search for
            pattern_pointnumbers = 'Point #(\d+)';
            pattern_spotnames = 'Comment: "(.*)"';
            pattern_spotsize = 'Spot Size: (\d+)';
            pattern_date = '\d{4}-\d{2}-\d{2}';
            pattern_rep_rate = 'Rep. Rate:';
            pattern_fired = 'Fired: (\d+)';

            % Initialize empty arrays and cells and variables
            pointnumbers = [];
            spotsize = [];
            measuringdate = {};
            spotnames = {};
            found_rep_rate = false;
            found_ablation = false;
            fired = [];

            % Extract the date from the first line
            lineone = fgets(logfile);
            matches_date = regexp(lineone, pattern_date, 'match');
            if ~isempty(matches_date)
                % Rewrite the date
                date_parts = split(matches_date{1}, '-');
                measuringdate = [date_parts{3}, date_parts{2}, date_parts{1}];
            end

            % Read the file line by line
            while ~feof(logfile)
                line = fgets(logfile);

                % check for = Ablation =
                if contains(line, '= Ablation =')
                    found_ablation = true;
                end

                % get the fired value
                if contains(line, 'Fired:') && found_ablation
                    % Extract the fired value
                    matches_fired = regexp(line, pattern_fired, 'tokens');
                    if ~isempty(matches_fired)
                        % Add the fired value to the array
                        fired = [fired; str2double(matches_fired{1}{1})];
                    end
                    found_ablation = false;
                end

                % get the spot number
                if contains(line, 'Point #')
                    % Extract the number after "Point #"
                    matches_pointnumbers = regexp(line, pattern_pointnumbers, 'tokens');
                    if ~isempty(matches_pointnumbers)
                        % Convert the number to a double and append it to the array
                        pointnumbers = [pointnumbers; str2double(matches_pointnumbers{1}{1})];
                    end
                end

                % get the spotname
                if contains(line, 'Comment:')
                    % Extract the comment
                    matches_spotnames = regexp(line, pattern_spotnames, 'tokens');
                    if ~isempty(matches_spotnames)
                        % Add the comment to the cell array
                        spotnames = [spotnames; matches_spotnames{1}{1}];
                    end
                end

                % check for Rep. Rate
                if contains(line, pattern_rep_rate)
                    found_rep_rate = true;
                end

                % get the spot size
                if contains(line, 'Spot Size:') && found_rep_rate
                    % Extract the spot size
                    matches_spotsize = regexp(line, pattern_spotsize, 'tokens');
                    if ~isempty(matches_spotsize)
                        % Add the spot size to the cell array
                        spotsize = [spotsize; str2double(matches_spotsize{1}{1})];
                    end
                    found_rep_rate = false;
                end
            end

            fclose(logfile);

            %% be sure that every entry made above is deleted here if the laser did not fire
            % this could be somehow connected to the check of time intervals of the
            % signals below

            for i=length(fired):-1:1
                if fired(i) == 0
                    % delete the ith entry in spotnames, spotsize, pointnumbers and fired
                    log_out_fun(app,[spotnames{i} ' has been deleted as the laser did not fire for this spot'])

                    spotnames(i) = [];
                    spotsize(i) = [];
                    pointnumbers(i) = [];
                    fired(i) = [];
                end
            end

            % output of this log file analyzer is:
            % spotnames, spotsize, pointnumbers
        end

        function [sliced_signals,icp_signals_processed,icp_signals_raw]  = cut_signals(app, input_filepath, output_filepath, measurement)

            %% importing the raw data
            icp_signals_raw = readtable(input_filepath);

            %% extracting informations out of the raw data

            icp_signals_processed = icp_signals_raw;
            icp_signals_processed.totalcounts = sum(table2array(icp_signals_raw(:,2:end)),2,'omitnan');
            icp_signals_processed.index = (1:height(icp_signals_processed))';

            %% define the background and the signal --> signal = 1, background = 0

            % Calculate the median of the total counts
            M = median(icp_signals_processed.totalcounts);
            % Calculate the Median Absolute Deviation of the total counts
            MAD = median(abs(icp_signals_processed.totalcounts - M));
            % Calculate the Robust Z-Score
            icp_signals_processed.robust_z_score = (icp_signals_processed.totalcounts - M) / MAD;
            % Classify the data points where the Robust Z-Score is less than a threshold as background
            app.threshold = 3; % 3 is about right %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            icp_signals_processed.classification(icp_signals_processed.robust_z_score < app.threshold) = 0;
            icp_signals_processed.classification(icp_signals_processed.robust_z_score >= app.threshold) = 1;

            %% clear out single spikes

            %Define a logical array
            logicalArray = icp_signals_processed.classification;
            % Reshape the logical array into a row vector
            logicalArray = reshape(logicalArray, 1, []);
            % Convert the logical array to a string
            charArray = num2str(logicalArray);
            % Remove any whitespace in the string
            charArray = strrep(charArray, ' ', '');
            % Define the pattern you want to find
            pattern_spike = '0{1}1{1}0{1}'; %for patterns like 010

            spikes = (regexp(charArray, pattern_spike))+1;

            for o = spikes
                icp_signals_processed.classification(o) = 0;
                icp_signals_processed.robust_z_score(o) = icp_signals_processed.robust_z_score(o+1);
            end

            %% now find where the signal ends to get an idea of how long every signal is

            % find local minima
            % Initialize an array to store the local minima
            icp_signals_processed.local_minima = true(size(icp_signals_processed.robust_z_score));

            % Iterate over the array, skipping the first and last elements
            for i = 1:length(icp_signals_processed.robust_z_score)
                % Check if the current element is an order of magnitude smaller than its neighbors
                % and if the current element is bigger than the threshold
                if icp_signals_processed.robust_z_score(i) >= app.threshold
                    if icp_signals_processed.robust_z_score(i) < icp_signals_processed.robust_z_score(i+5)/10 %%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                        icp_signals_processed.local_minima(i) = false;
                    end
                else
                    icp_signals_processed.local_minima(i) = false;
                end
            end


            starts = find(diff(icp_signals_processed.local_minima == 1) == 1);
            ends = find(diff(icp_signals_processed.local_minima == 1) == -1);


            %now filter the ends of the signals
            %if the length between the start of a signal and its end is more than 30
            %rows it is a proper signal, otherwise it is a cleaning shot

            signal_starts = [];
            signal_ends = [];
            cleaningshot_starts = [];
            cleaningshot_ends = [];

            % Calculate the lengths of all signals
            lengths = ends - starts;

            % Calculate the median length
            median_length = median(lengths);

            % Classify the signals
            for i = 1:length(ends)
                if ends(i) - starts(i) > median_length - (median_length/3)
                    signal_starts = [signal_starts; starts(i)];
                    signal_ends = [signal_ends; ends(i)];
                else
                    cleaningshot_starts = [cleaningshot_starts; starts(i)];
                    cleaningshot_ends = [cleaningshot_ends; ends(i)];
                end
            end

            distancebetweensignalends = diff(signal_ends);
            median_distancebetweensignalends = median(distancebetweensignalends);

            median_cleaningshotlength = round(median(cleaningshot_ends- cleaningshot_starts));
            median_signallength = round(median(signal_ends- signal_starts));

            %% slice all signals into blocks using always the ends of the signals

            %get the first signal
            start_index = max(1, signal_ends(1)-median_distancebetweensignalends);
            sliced_signals(1).signal = icp_signals_processed(start_index:signal_ends(1)+5, 1:size(icp_signals_raw, 2));

            %get all signals between the first and the last one
            for i = 2:length(signal_ends)
                % Check if next index is within bounds
                if i <= length(signal_ends)
                    sliced_signals(i).signal = icp_signals_processed(signal_ends(i-1):signal_ends(i)+5, 1:size(icp_signals_raw, 2));
                else
                    % Handle the last signal separately
                    sliced_signals(i).signal = icp_signals_processed(signal_ends(i-1):end, 1:size(icp_signals_raw, 2));
                end
            end


            %% check if there are as many signals in the logfile as where accounted for
            % % in the csv file.
            if size(sliced_signals,2)==numel(measurement.spotnames)
                log_out_fun(app,'The program detected the SAME amount of signals in the .csv file and in the logfile --> it worked')
            else
                log_out_fun(app,'The program detected a DIFFERENT amount of signals in the .csv file and in the logfile --> something failed')
            end

         %% save the ouput
            for h = 1:length(sliced_signals)%measurement.spotnames)
                % Generate the filename
                outputpath = fullfile(output_filepath, [num2str(measurement.pointnumbers(h)) '_' measurement.spotnames{h} '.csv']);

                % Write the table to the file
                writetable(sliced_signals(h).signal, outputpath);
            end
          

        end

        % prints an problem, if there is one, in the infos_textarea
        function  log_out_fun(app,newtext)
            app.infos_textarea.Value = [string(app.infos_textarea.Value);string(newtext)];
        end

        %makes the copy of an input table sliced_signals.signal and sums up
        function update_active_table_fun(app)

            T=app.data.measurement(app.eId).sliced_signals(app.selected_signal).signal;

            T.totalcounts = sum(table2array(T(:,2:end)),2,'omitnan');
            app.active_table=T;
        end


%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%% Visual Selection

        function plot_allsignals(app)

            y_axis = app.data.measurement(app.eId).all_signals.totalcounts;

             % Plot the data
            app.line = plot(app.allsignals_axes, y_axis, 'k', 'LineWidth', 2);
            app.allsignals_axes.YScale = "log";
            ylim(app.allsignals_axes, [min(y_axis), inf]);
            xlabel(app.allsignals_axes, 'Sweeps');
            ylabel(app.allsignals_axes, 'Total Counts per second');


        end


        function data_selector(app)

            % rectangle i need to write this as a function and return the x and y data in the rectangle from the function
            %this x and y data is then passed to a output function which is
            %activated by the other button and outputs the selected data as
            %a table with the name given in the textfield

            % Draw a rectangle on the plot
            app.rect = drawrectangle(app.allsignals_axes, 'Color', [0.5 0.5 0.5], 'LineWidth', 0.5);

            % Get the position and limints of the rectangle
            pos = app.rect.Position;
            xLimits = [pos(1), pos(1) + pos(3)];
            yLimits = [pos(2), pos(2) + pos(4)];

            % Extract the data from the line
            xData = get(app.line, 'XData');
            yData = get(app.line, 'YData');

            % Determine which data points are within the rectangle
            inRect = xData >= xLimits(1) & xData <= xLimits(2) & yData >= yLimits(1) & yData <= yLimits(2);
            app.data.measurement(app.eId).xDataInRect = xData(inRect);
            app.data.measurement(app.eId).yDataInRect = yData(inRect);

        end


        function visual_cutter(app)

        
            % Get the number of columns and extract all of them except the second
            % to last one (which are the totalcounts)
            numCols = size(app.data.measurement(app.eId).all_signals, 2);
            app.data.measurement(app.eId).single_signal = app.data.measurement(app.eId).all_signals(app.data.measurement(app.eId).xDataInRect, 1:numCols-1);


        end


        function csvoutputgenerator(app)

            if isempty(app.SpotnameEditField.Value) == true
                message = ["Spotname is missing"];
                uialert(app.LASAUIFigure,message,"Warning", ...
                    "Icon","warning");
                return
            else
                filename = app.SpotnameEditField.Value;
            end

            %define the filepath and the name of the csv output
            filepath = fullfile(app.outputfolder, [filename, '.csv']);

            %% Add the timestamps needed for Drift Correction %%%%
            % first get the minimum and maximum values
            starttime = min(app.data.measurement(app.eId).single_signal.Time_Sec_);
            endtime = max(app.data.measurement(app.eId).single_signal.Time_Sec_);
            % format the seconds to the right HH:MM:SS format
            startdate = datestr(seconds(starttime),'HH:MM:SS');
            enddate = datestr(seconds(endtime),'HH:MM:SS');

            % define the headers
            header1 = 'D:\hello\world.d';
            header2 = 'Intensity Vs Time,CPS';
            header3 = ['Acquired      : 01/01/1111 ', startdate, ' using Batch Enjoy_the_Process.b'];

            % open the file with write permission
            fid = fopen(filepath, 'w');

            % write the headers
            fprintf(fid, '%s\n%s\n%s\n', header1, header2, header3);

            %%% I'm not 100% sure if this is not problematic because the
            %%% actual time a sweep takes is replaced
            % Replace the values in the 'Time_Sec_' column with a sequence
            % of floats starting at 0.75 and always increasing 0.75.
            % this is done to be as PySILLS like as possible
            numRows = height(app.data.measurement(app.eId).single_signal);
            app.data.measurement(app.eId).single_signal.Time_Sec_ = (0.75:0.75:(numRows*0.75))';

            % Rename the 'Time_Sec_' column to 'Time[Sec]'
            % this is done to be as PySILLS like as possible. Matlab doesnt
            % normally allow Time[Sec] as a variable name.
            app.data.measurement(app.eId).single_signal.Properties.VariableNames{'Time_Sec_'} = 'Time [Sec]';

            % write the element names to the file because otherwise they
            % get overwritten by the headers defined above.
            varNames = app.data.measurement(app.eId).single_signal.Properties.VariableNames;
            % join the variable names with a comma
            varNamesStr = strjoin(varNames, ',');
            % write the variable names to the file. NO TRAILING COMMA!!!
            fprintf(fid, '%s', varNamesStr);

            % close the file
            fclose(fid);

            % write the table to the file, appending it to the headers
            writetable(app.data.measurement(app.eId).single_signal, filepath, 'WriteMode', 'append');

            % reopen the file in append mode and write the "Printed" line
            fid = fopen(filepath, 'a');
            fprintf(fid, '\n\n          Printed:01/01/1111 %s', enddate);
            fclose(fid);

            % Define the .txt file path
            txtfilepath = fullfile(app.outputfolder, 'filenames_and_startdates.txt');

            % Open the .txt file with append permission
            txtfid = fopen(txtfilepath, 'a');

            % Write the filename and the startdate to the .txt file
            fprintf(txtfid, '%s, %s\n', filename, startdate);

            % Close the .txt file
            fclose(txtfid);

            %https://ch.mathworks.com/help/matlab/ref/matlab.lang.makeuniquestrings.html
            %to auto generate unique names

        end


    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %this is here so that during development stage I dont have to
            %choose the paths every time

            app.agilentcsvpath_textfield.Value='D:\OneDrive - Universitaet Bern\PhD\Data\Almirez\LA-ICP-MS Data\Minerals\30.11.2023\Alm-Min-1.csv';
            app.logfilepath_textfield.Value='D:\OneDrive - Universitaet Bern\PhD\Data\Almirez\LA-ICP-MS Data\Minerals\30.11.2023\Alm06_Min_log_20231130_172714.log';
            app.outputpath_textfield.Value='D:\OneDrive - Universitaet Bern\PhD\Data\Almirez\LA-ICP-MS Data\Minerals\30.11.2023\test GUI 1';


        end

        % Button pushed function: agilentcsvpath_button
        function agilentcsvpath_buttonPushed(app, event)
            % if the button is pushed it asks for a file with .cvs ending

            [filename, pathname] = uigetfile('*.csv', 'Select a CSV file');
            fullfilename = fullfile(pathname, filename);
            %if he asks but no file is selected but cancel is pressed it is
            %not a problem anymore (I am not sure how though...)
            if not(filename==0)

                app.agilentcsvpath_textfield.Value = fullfilename;
            end

            figure(app.LASAUIFigure)
        end

        % Button pushed function: logfilepath_button
        function logfilepath_buttonPushed(app, event)
            % if the button is pushed it asks for a file with .log ending
            [filename, pathname] = uigetfile('*.log', 'Select a log file');
            fullfilename = fullfile(pathname, filename);
             %if he asks but no file is selected but cancel is pressed it is
            %not a problem anymore (I am not sure how though...)
            if not(filename==0)

                app.logfilepath_textfield.Value = fullfilename;
            end

            figure(app.LASAUIFigure)
        end

        % Button pushed function: outputpath_button
        function outputpath_buttonPushed(app, event)
             % if the button is pushed it asks for a folder to export the
             % csv files of the signals into
            pathname = uigetdir;
            %if he asks but no file is selected but cancel is pressed it is
            %not a problem anymore (I am not sure how though...)
            if not(pathname==0)

                app.outputpath_textfield.Value = pathname;
            end

            figure(app.LASAUIFigure)
        end

        % Button pushed function: start_button
        function start_buttonPushed(app, event)
            % entryID counter so that it always goes up 1 if there already
            % is a field which is called measurement
            if not (isempty(app.data)) && isfield(app.data,'measurement')&& size(app.data.measurement,2)>0
                app.eId= size(app.data.measurement,2)+1;
            else
                app.eId=1;
            end

% Idee fuer failsafe: wenn er abbricht alles rueckgaengig machen
% wenn nicht dann eId -1 damit er keine leeren zeilen reinschreibt sondern
% er die falschen zeilen wieder ueberschreibt.
            
% display the entry ID
app.eId_textfield.Value=app.eId;

% pick the laser of your choice. is important to know because of the
% different types of logfiles

            switch app.lasertype.Value
                % call the logfile_analyzer function and save the outputs 
                case 'new laser'
                    [pointnumbers,spotsize,spotnames,fired,app.data.measuringdate{app.eId}] = logfile_analyzer_newLaser(app,app.logfilepath_textfield.Value);
                case 'old laser'
                    message = ["Has not been integrated yet"];
                    uialert(app.LASAUIFigure,message,"Warning", ...
                        "Icon","warning");
                    return
                    %[pointnumbers,spotsize,spotnames,fired,app.data.measuringdate{app.eId}] = logfile_analyzer_old(app,app.logfilepath_textfield.Value);
                case 'no logfile'
                    message = ["Has not been integrated yet"];
                    uialert(app.LASAUIFigure,message,"Warning", ...
                        "Icon","warning");
                    return
                    %[pointnumbers,spotsize,spotnames,fired,app.data.measuringdate{app.eId}] = logfile_analyzer_noLaser(app,app.logfilepath_textfield.Value);
            end

% store the output arrays from the logfile_analyzer function in the in-app data structure
            app.data.measurement(app.eId).pointnumbers = pointnumbers;
            app.data.measurement(app.eId).spotsize = spotsize;
            app.data.measurement(app.eId).spotnames = spotnames;
            app.data.measurement(app.eId).fired = fired;

%slice the signals with the cut_signals function
            [sliced_signals,icp_signals_processed,icp_signals_raw] = cut_signals(app,app.agilentcsvpath_textfield.Value, app.outputpath_textfield.Value, app.data.measurement(app.eId));

%store the output of the cut_signals function in the in-app data structure
            app.data.measurement(app.eId).icp_signals_raw = icp_signals_raw;
            app.data.measurement(app.eId).icp_signals_processed = icp_signals_processed;
            app.data.measurement(app.eId).sliced_signals = sliced_signals;

%read the background value from the cut_signals function in the textfield.
%I'm not sure how he gets the guessed_value_bkgd though

            app.zscore_textfield.Value = app.threshold;

%update the whole GUI
            update_gui_fun(app)

        end

        % Value changed function: spotname_dropdown
        function spotname_dropdownValueChanged(app, event)
% the spotname_dropdown no changes the selected_signal

            app.selected_signal = app.spotname_dropdown.Value;
%update the listbox of the elements and the figure
            update_listbox_fun(app)

            update_fig_fun(app)
        end

        % Value changed function: elements_listbox
        function elements_listboxValueChanged(app, event)
            % i'm not 100% sure but I think this controls which elements
            % get displayed if you select them
            app.selected_elements = app.elements_listbox.Value;

            update_fig_fun(app)
        end

        % Value changed function: log_checkbox
        function logCheckBoxValueChanged(app, event)
            % updates the plot to logarithimic if it is checked
            value = app.log_checkbox.Value;
            update_fig_fun(app)
        end

        % Button pushed function: previoussignal_button
        function previoussignal_buttonPushed(app, event)
            %check if this is the first selected_signal, if yes and you
            %press back it jumps to the last signal, otherwise it just goes
            %one signal back
            if app.selected_signal==1

                app.selected_signal=numel(app.spotname_dropdown.Items);
                app.spotname_dropdown.Value=numel(app.spotname_dropdown.Items);

            else
                app.selected_signal=app.selected_signal-1;
                app.spotname_dropdown.Value=app.spotname_dropdown.Value-1;


            end
%update the element list and the plot
            update_listbox_fun(app)

            update_fig_fun(app)
        end

        % Button pushed function: nextsignal_button
        function nextsignal_buttonPushed(app, event)
            %check if this is the first selected_signal, if yes and you
            %press it jumps to the first signal, otherwise it just goes
            %one signal forward
            if app.selected_signal==numel(app.spotname_dropdown.Items)

                app.selected_signal=1;
                app.spotname_dropdown.Value=1;


            else
                app.selected_signal=app.selected_signal+1;
                app.spotname_dropdown.Value=app.spotname_dropdown.Value+1;
            end
            %update the element list and the plot
            update_listbox_fun(app)

            update_fig_fun(app)
        end

        % Value changed function: zscore_textfield
        function zscore_textfieldValueChanged(app, event)
            %displays the backgroundvalue 
            value = app.threshold.Value;

        end

        % Menu selected function: LoadProjectMenu
        function LoadProjectMenuSelected(app, event)
            %load %%%%%%%%%% Work in Progress %%%%%%%%%%%

            %update_gui_fun(app)         update   app.eId_textfield.Value=app.eId;

        end

        % Menu selected function: SaveProjectMenu
        function SaveProjectMenuSelected(app, event)
           %save %load %%%%%%%%%% Work in Progress %%%%%%%%%%%
            %"new" is also needed

            
        end

        % Button pushed function: agilentcsvpath_vis_button
        function agilentcsvpath_vis_buttonPushed(app, event)
             % if the button is pushed it asks for a file with .cvs ending

            [filename_oldlaser, pathname_oldlaser] = uigetfile('*.csv', 'Select a CSV file');
            fullfilename_oldlaser = fullfile(pathname_oldlaser, filename_oldlaser);
            %if he asks but no file is selected but cancel is pressed it is
            %not a problem anymore (I am not sure how though...)
            if not(filename_oldlaser==0)
                fullfilename_oldlaser = fullfile(pathname_oldlaser, filename_oldlaser);
                app.table_oldlaser = fullfilename_oldlaser;

                %writes the chosen pathname in the textfield
                app.agilentcsvpath_vis_textfield.Value = pathname_oldlaser;
            end

            figure(app.LASAUIFigure)
        end

        % Button pushed function: outputpath_vis_button
        function outputpath_vis_buttonPushed(app, event)
              % if the button is pushed it asks for a folder to export the
             % csv files of the signals into
            app.outputfolder = uigetdir;
            %if he asks but no file is selected but cancel is pressed it is
            %not a problem anymore (I am not sure how though...)
            if not(app.outputfolder==0)

                app.outputpath_vis_textfield.Value = app.outputfolder;
            end

            figure(app.LASAUIFigure)
        end

        % Button pushed function: importsignals_button
        function importsignals_buttonButtonPushed(app, event)
          
            %writes a message if no Inputfile has been selected but the
            %buttton is pressed
            if isempty(app.table_oldlaser)
                message = ["No Inputfile has been selected"];
                uialert(app.LASAUIFigure,message,"Warning", ...
                    "Icon","warning");
                return
            else
                %reads in the table and sums up the counts to toalcounts
                app.data.measurement(app.eId).all_signals = readtable(app.table_oldlaser);
                app.data.measurement(app.eId).all_signals.totalcounts = sum(table2array(app.data.measurement(app.eId).all_signals(:,2:end)),2,'omitnan');
                % calls the plot function defined above and plots the data
                plot_allsignals(app)
            end
    

        end

        % Button pushed function: enable_zoom_button
        function enable_zoom_buttonButtonPushed(app, event)
            %enables zoom
            zoom(app.allsignals_axes, 'on');

        end

        % Button pushed function: draw_rectangle_button
        function draw_rectangle_buttonButtonPushed(app, event)
            %first disables zoom and panning
            zoom(app.allsignals_axes, 'off');
            pan(app.allsignals_axes, 'off');
            %deletes the already existing rectangle if there is one
            delete(app.rect);
            %calls the 
            data_selector(app)


        end

        % Button pushed function: CutSaveButton
        function CutSaveButtonPushed(app, event)


            visual_cutter(app)
            csvoutputgenerator(app)

            
        end

        % Value changed function: SpotnameEditField
        function SpotnameEditFieldValueChanged(app, event)
            value = app.SpotnameEditField.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.LASAUIFigure = uifigure('Visible', 'off');
            app.LASAUIFigure.Name = 'LASA';
            app.LASAMenu = uimenu(app.LASAUIFigure);
            app.LASAMenu.Text = 'LASA';
            app.LoadProjectMenu = uimenu(app.LASAMenu);
            app.LoadProjectMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadProjectMenuSelected, true);
            app.LoadProjectMenu.Text = 'Load Project';
            app.SaveProjectMenu = uimenu(app.LASAMenu);
            app.SaveProjectMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveProjectMenuSelected, true);
            app.SaveProjectMenu.Text = 'Save Project';
            app.GridLayout = uigridlayout(app.LASAUIFigure);
            app.TabGroup = uitabgroup(app.GridLayout);
            app.Input = uitab(app.TabGroup);
            app.Input.Title = 'Input';
            app.GridLayout2 = uigridlayout(app.Input);
            app.agilentcsvpath_button = uibutton(app.GridLayout2, 'push');
            app.agilentcsvpath_button.ButtonPushedFcn = createCallbackFcn(app, @agilentcsvpath_buttonPushed, true);
            app.agilentcsvpath_button.Text = 'Set csv Path';
            app.logfilepath_button = uibutton(app.GridLayout2, 'push');
            app.logfilepath_button.ButtonPushedFcn = createCallbackFcn(app, @logfilepath_buttonPushed, true);
            app.logfilepath_button.Text = 'Set Logfile Path';
            app.outputpath_button = uibutton(app.GridLayout2, 'push');
            app.outputpath_button.ButtonPushedFcn = createCallbackFcn(app, @outputpath_buttonPushed, true);
            app.outputpath_button.Text = 'Set output folder';
            app.start_button = uibutton(app.GridLayout2, 'push');
            app.start_button.ButtonPushedFcn = createCallbackFcn(app, @start_buttonPushed, true);
            app.start_button.Text = 'Do it';
            app.infosTextAreaLabel = uilabel(app.GridLayout2);
            app.infosTextAreaLabel.Text = 'infos';
            app.infos_textarea = uitextarea(app.GridLayout2);
            app.inputcsvEditFieldLabel = uilabel(app.GridLayout2);
            app.inputcsvEditFieldLabel.Text = 'input csv';
            app.agilentcsvpath_textfield = uieditfield(app.GridLayout2, 'text');
            app.logEditFieldLabel = uilabel(app.GridLayout2);
            app.logEditFieldLabel.Text = 'log';
            app.logfilepath_textfield = uieditfield(app.GridLayout2, 'text');
            app.outEditFieldLabel = uilabel(app.GridLayout2);
            app.outEditFieldLabel.Text = 'out';
            app.outputpath_textfield = uieditfield(app.GridLayout2, 'text');
            app.DropDownLabel = uilabel(app.GridLayout2);
            app.DropDownLabel.Text = 'Drop Down';
            app.lasertype = uidropdown(app.GridLayout2);
            app.lasertype.Items = {'new laser', 'old laser', 'no logfile'};
            app.lasertype.Value = 'new laser';
            app.eIdEditFieldLabel = uilabel(app.GridLayout2);
            app.eIdEditFieldLabel.Text = 'eId';
            app.eId_textfield = uieditfield(app.GridLayout2, 'numeric');
            app.eId_textfield.Editable = 'off';
            app.Inspection = uitab(app.TabGroup);
            app.Inspection.Title = 'Inspection';
            app.GridLayout5 = uigridlayout(app.Inspection);
            app.Panel = uipanel(app.GridLayout5);
            app.GridLayout3 = uigridlayout(app.Panel);
            app.signal_axes = uiaxes(app.GridLayout3);
            xlabel(app.signal_axes, 'Time [s]')
            ylabel(app.signal_axes, 'Counts')
            zlabel(app.signal_axes, 'Z')
            app.previoussignal_button = uibutton(app.GridLayout3, 'push');
            app.previoussignal_button.ButtonPushedFcn = createCallbackFcn(app, @previoussignal_buttonPushed, true);
            app.previoussignal_button.Text = '<';
            app.nextsignal_button = uibutton(app.GridLayout3, 'push');
            app.nextsignal_button.ButtonPushedFcn = createCallbackFcn(app, @nextsignal_buttonPushed, true);
            app.nextsignal_button.Text = '>';
            app.log_checkbox = uicheckbox(app.GridLayout3);
            app.log_checkbox.ValueChangedFcn = createCallbackFcn(app, @logCheckBoxValueChanged, true);
            app.log_checkbox.Text = 'log';
            app.log_checkbox.Value = true;
            app.elements_listbox = uilistbox(app.GridLayout3);
            app.elements_listbox.Multiselect = 'on';
            app.elements_listbox.ValueChangedFcn = createCallbackFcn(app, @elements_listboxValueChanged, true);
            app.elements_listbox.Value = {'Item 1'};
            app.SpotNameDropDownLabel = uilabel(app.GridLayout3);
            app.SpotNameDropDownLabel.Text = 'Spot Name';
            app.spotname_dropdown = uidropdown(app.GridLayout3);
            app.spotname_dropdown.Items = {'Select'};
            app.spotname_dropdown.ValueChangedFcn = createCallbackFcn(app, @spotname_dropdownValueChanged, true);
            app.spotname_dropdown.Value = 'Select';
            app.zscorethresholdLabel = uilabel(app.GridLayout3);
            app.zscorethresholdLabel.Text = 'z-score threshold';
            app.zscore_textfield = uieditfield(app.GridLayout3, 'numeric');
            app.zscore_textfield.ValueChangedFcn = createCallbackFcn(app, @zscore_textfieldValueChanged, true);
            app.zscore_textfield.Editable = 'off';
            app.visual_selection = uitab(app.TabGroup);
            app.visual_selection.Title = 'Visual Selection';
            app.GridLayout4 = uigridlayout(app.visual_selection);
            app.allsignals_axes = uiaxes(app.GridLayout4);
            xlabel(app.allsignals_axes, 'Sweeps')
            ylabel(app.allsignals_axes, 'Counts')
            zlabel(app.allsignals_axes, 'Z')
            app.EditFieldLabel = uilabel(app.GridLayout4);
            app.EditFieldLabel.Text = '';
            app.agilentcsvpath_vis_textfield = uieditfield(app.GridLayout4, 'text');
            app.CutSaveButton = uibutton(app.GridLayout4, 'push');
            app.CutSaveButton.ButtonPushedFcn = createCallbackFcn(app, @CutSaveButtonPushed, true);
            app.CutSaveButton.Text = 'Cut & Save';
            app.agilentcsvpath_vis_button = uibutton(app.GridLayout4, 'push');
            app.agilentcsvpath_vis_button.ButtonPushedFcn = createCallbackFcn(app, @agilentcsvpath_vis_buttonPushed, true);
            app.agilentcsvpath_vis_button.Text = 'Inputpath';
            app.Label = uilabel(app.GridLayout4);
            app.Label.Text = '';
            app.outputpath_vis_textfield = uieditfield(app.GridLayout4, 'text');
            app.outputpath_vis_button = uibutton(app.GridLayout4, 'push');
            app.outputpath_vis_button.ButtonPushedFcn = createCallbackFcn(app, @outputpath_vis_buttonPushed, true);
            app.outputpath_vis_button.Text = 'Outputpath';
            app.SpotnameEditFieldLabel = uilabel(app.GridLayout4);
            app.SpotnameEditFieldLabel.Text = 'Spotname';
            app.SpotnameEditField = uieditfield(app.GridLayout4, 'text');
            app.SpotnameEditField.ValueChangedFcn = createCallbackFcn(app, @SpotnameEditFieldValueChanged, true);
            app.importsignals_button = uibutton(app.GridLayout4, 'push');
            app.importsignals_button.ButtonPushedFcn = createCallbackFcn(app, @importsignals_buttonButtonPushed, true);
            app.importsignals_button.Text = 'Import Signals';
            app.draw_rectangle_button = uibutton(app.GridLayout4, 'push');
            app.draw_rectangle_button.ButtonPushedFcn = createCallbackFcn(app, @draw_rectangle_buttonButtonPushed, true);
            app.draw_rectangle_button.Text = 'Draw Rectangle';
            app.enable_zoom_button = uibutton(app.GridLayout4, 'push');
            app.enable_zoom_button.ButtonPushedFcn = createCallbackFcn(app, @enable_zoom_buttonButtonPushed, true);
            app.enable_zoom_button.Text = 'Enable Zoom';
            % Show the figure after all components are created
            app.LASAUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = LASA_v5_for_PySILLS

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.LASAUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.LASAUIFigure)
        end
    end
end