classdef LineXlim_demo < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        GridLayout_PlotSettings  matlab.ui.container.GridLayout
        xlimMax_p1               matlab.ui.control.Button
        xlimMax_p10              matlab.ui.control.Button
        xlimMin_p10              matlab.ui.control.Button
        xlimMin_p1               matlab.ui.control.Button
        EditField_xlimMin        matlab.ui.control.NumericEditField
        xlimMin_m1               matlab.ui.control.Button
        xlimMin_m10              matlab.ui.control.Button
        ResetButton              matlab.ui.control.Button
        xlimMax_m10              matlab.ui.control.Button
        xlimMax_m1               matlab.ui.control.Button
        EditField_xlimMax        matlab.ui.control.NumericEditField
        FigXlimLive              matlab.ui.control.UIAxes
        ZoomedPlot               matlab.ui.control.UIAxes
    end


    properties (Access = private)
        hVerticalLines % Description
        hLineToDrag
        xlimits


        y_signal=rand(10000,1);
        x_signal=(1:10000)';

    end

    methods (Access = private)

        function reset_zoom_pan(app)
            try
                zoom(app.ZoomedPlot,'off')
                pan(app.ZoomedPlot,'off')
            catch ME
                disp('Error - reset zoom / pan')
                disp(ME)

            end
        end


        function  update_FigXlimLive(app)
            cla(app.FigXlimLive)
            clear_plots=false;
            if clear_plots==true

                app.hVerticalLines(1:2) = [xline(app.FigXlimLive,app.EditField_xlimMin.Value,'Color','red',"LineWidth",3)...
                    xline(app.FigXlimLive, app.EditField_xlimMax.Value,'Color','blue',"LineWidth",3)];
                set(app.hVerticalLines(1:2), 'hittest', 'off');
                xlim(app.FigXlimLive,[app.FigXlimLive,app.EditField_xlimMin.Value app.EditField_xlimMax.Value])

            else
              

                a_h=area(app.FigXlimLive,app.x_signal,app.y_signal,'FaceColor',[0.8 0.8 0.8],'LineWidth',1);
                set(a_h, 'hittest', 'off');

                range=app.x_signal>=app.EditField_xlimMin.Value & app.x_signal<=app.EditField_xlimMax.Value;
       

                l_h=plot(app.FigXlimLive,app.x_signal(range,:),max(app.y_signal(range,:))./(max(mean(app.y_signal(range,:),2,"omitnan"))./mean(app.y_signal(range,:),2,"omitnan")),'LineStyle',':','Color','k');
                set(l_h, 'hittest', 'off');

                app.hVerticalLines(1:2) = [xline(app.FigXlimLive,app.EditField_xlimMin.Value,'Color','red',"LineWidth",3)...
                    xline(app.FigXlimLive, app.EditField_xlimMax.Value,'Color','blue',"LineWidth",3)];

                set(app.hVerticalLines(1:2), 'hittest', 'off');

                xlimval=[min([app.EditField_xlimMin.Value min(app.x_signal(range,:))])...
                    max([app.EditField_xlimMax.Value max(app.x_signal(range,:))])];
               app.xlimits=xlimval;
                try
                    xlim(app.ZoomedPlot,xlimval)
                catch
                    disp('no xlim update in xlim(app.FigXlimLive,xlimval')
                end

            end
        end
        
        function UpdateZoomPlot(app)
            cla(app.ZoomedPlot)    
            plot(app.ZoomedPlot,app.x_signal,app.y_signal,'LineWidth',1,'Color','k','LineStyle','-')
           
            if min(app.xlimits)==max(app.xlimits)
                xlim(app.ZoomedPlot,'auto')
            else
                xlim(app.ZoomedPlot,sort([min(app.xlimits) max(app.xlimits)]))
            end

        end
        
        
        end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            xlimval=[min([app.EditField_xlimMin.Value min(app.x_signal)])...
                max([app.EditField_xlimMax.Value max(app.x_signal)])];
            app.EditField_xlimMax.Value=max(xlimval);
            app.EditField_xlimMin.Value=min(xlimval);

            y=    magic(100);
            app.y_signal=y(:);

            app.xlimits=xlimval;
            UpdateZoomPlot(app)
            update_FigXlimLive(app)

        end

        % Button down function: FigXlimLive
        function FigXlimLiveButtonDown(app, event)
            reset_zoom_pan(app)
            for k=1:2
                xVertLineCoord = get(app.hVerticalLines(k),'Value');
                click_buffer=(max(app.FigXlimLive.XLim)-min(app.FigXlimLive.XLim))*0.01; %ATH Settingspanel
                xVertLineCoord(1)=xVertLineCoord(1);
                if abs(event.IntersectionPoint(1) - xVertLineCoord(1)) < click_buffer
                    app.hLineToDrag = app.hVerticalLines(k);
                    break;
                elseif get(app.hVerticalLines(1),'Value')<abs(event.IntersectionPoint(1)) & get(app.hVerticalLines(2),'Value')>abs(event.IntersectionPoint(1))
                    app.hLineToDrag = app.hVerticalLines(1:2);
                    break;

                end

            end
        end

        % Window button motion function: UIFigure
        function UIFigureWindowButtonMotion(app, event)

            if ~isempty(app.hLineToDrag)

                currentPoint   = app.FigXlimLive.CurrentPoint;
                x            = currentPoint(1,1);
                %y            = currentPoint(1,3);
                xlim = app.FigXlimLive.XLim;
                %ylim = app.FigXlimLive.YLim;
                inside_FigXlimLive = x >= xlim(1) & x <= xlim(2); %& y >= ylim(1) & y <= ylim(2) % x >= xlim(1) & x <= xlim(2) & y >= ylim(1) & y <= ylim(2)
                
                if inside_FigXlimLive
                    numel(app.hLineToDrag)
                    if numel(app.hLineToDrag)==2
                        xposlines=[get(app.hVerticalLines(1),'Value') get(app.hVerticalLines(2),'Value')];
                        difference=range(xposlines)/2; % here get the difference between the lines

                        x1=x-difference;
                        x2=x+difference;
                        set(app.hLineToDrag(1), 'Value', x1);
                        set(app.hLineToDrag(2), 'Value', x2);

                        xposlines=[get(app.hVerticalLines(1),'Value') get(app.hVerticalLines(2),'Value')];
                        app.EditField_xlimMin.Value = min(xposlines);
                        app.EditField_xlimMax.Value = max(xposlines);


                    else
                        set(app.hLineToDrag, 'Value', x);
                        xposlines=[get(app.hVerticalLines(1),'Value') get(app.hVerticalLines(2),'Value')];
                        app.EditField_xlimMin.Value = min(xposlines);
                        app.EditField_xlimMax.Value = max(xposlines);
                    end
                else
                    UIFigureWindowButtonUp(app)
                end

                  
              end


        end

        % Window button up function: UIFigure
        function UIFigureWindowButtonUp(app, event)
            if ~isempty(app.hLineToDrag)

                app.hLineToDrag = [];

                app.xlimits=[app.EditField_xlimMin.Value app.EditField_xlimMax.Value];
                set(app.hVerticalLines(1),'Value',app.EditField_xlimMin.Value)
                set(app.hVerticalLines(2),'Value',app.EditField_xlimMax.Value)        

                UpdateZoomPlot(app)
                update_FigXlimLive(app)
            end
        end

        % Value changed function: EditField_xlimMax, EditField_xlimMin
        function EditField_LiveXMinMaxValueChanged(app, event)
             ValueMin = app.EditField_xlimMin.Value;
            ValueMax = app.EditField_xlimMax.Value;

            if ValueMin==ValueMax
                0;
            end

            if ValueMin < ValueMax
                try
                    set(app.hVerticalLines(1),'Value',ValueMin)
                    set(app.hVerticalLines(2),'Value',ValueMax)
                catch
                    app.hVerticalLines(1:2) = [xline(app.FigXlimLive,app.EditField_xlimMin.Value,'Color','red',"LineWidth",3)...
                        xline(app.FigXlimLive, app.EditField_xlimMax.Value,'Color','blue',"LineWidth",3)];
                end

            end


                xlimval=[min([app.EditField_xlimMin.Value min(app.x_signal)])...
                    max([app.EditField_xlimMax.Value max(app.x_signal)])];
                xlim(app.FigXlimLive,xlimval)


            UpdateZoomPlot(app)

        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            xlimval=[min([app.EditField_xlimMin.Value min(app.x_signal)])...
                max([app.EditField_xlimMax.Value max(app.x_signal)])];
            app.EditField_xlimMax.Value=max(xlimval);
            app.EditField_xlimMin.Value=min(xlimval);

            app.xlimits=xlimval;
            UpdateZoomPlot(app)
            update_FigXlimLive(app)

        end

        % Button pushed function: xlimMin_p10
        function xlimMin_p10ButtonPushed(app, event)
            app.EditField_xlimMin.Value=app.EditField_xlimMin.Value+10;
            EditField_LiveXMinMaxValueChanged(app)
        end

        % Button pushed function: xlimMin_p1
        function xlimMin_p1ButtonPushed(app, event)
                  app.EditField_xlimMin.Value=app.EditField_xlimMin.Value+1;
            EditField_LiveXMinMaxValueChanged(app)
        end

        % Button pushed function: xlimMin_m1
        function xlimMin_m1ButtonPushed(app, event)
            app.EditField_xlimMin.Value=app.EditField_xlimMin.Value-1;
            EditField_LiveXMinMaxValueChanged(app)
        end

        % Button pushed function: xlimMin_m10
        function xlimMin_m10ButtonPushed(app, event)
               app.EditField_xlimMin.Value=app.EditField_xlimMin.Value-10;
            EditField_LiveXMinMaxValueChanged(app)
        end

        % Button pushed function: xlimMax_p1
        function xlimMax_p1ButtonPushed(app, event)
             app.EditField_xlimMax.Value=app.EditField_xlimMax.Value+1;
            EditField_LiveXMinMaxValueChanged(app)
        end

        % Button pushed function: xlimMax_p10
        function xlimMax_p10ButtonPushed(app, event)
            
  app.EditField_xlimMax.Value=app.EditField_xlimMax.Value+10;
            EditField_LiveXMinMaxValueChanged(app)

        end

        % Button pushed function: xlimMax_m1
        function xlimMax_m1ButtonPushed(app, event)
                    app.EditField_xlimMax.Value=app.EditField_xlimMax.Value-1;
            EditField_LiveXMinMaxValueChanged(app)
        end

        % Button pushed function: xlimMax_m10
        function xlimMax_m10ButtonPushed(app, event)
            app.EditField_xlimMax.Value=app.EditField_xlimMax.Value-10;
            EditField_LiveXMinMaxValueChanged(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.WindowButtonUpFcn = createCallbackFcn(app, @UIFigureWindowButtonUp, true);
            app.UIFigure.WindowButtonMotionFcn = createCallbackFcn(app, @UIFigureWindowButtonMotion, true);
            app.GridLayout_PlotSettings = uigridlayout(app.UIFigure);
            app.ZoomedPlot = uiaxes(app.GridLayout_PlotSettings);
            xlabel(app.ZoomedPlot, 'X')
            ylabel(app.ZoomedPlot, 'Y')
            zlabel(app.ZoomedPlot, 'Z')
            app.FigXlimLive = uiaxes(app.GridLayout_PlotSettings);
            app.FigXlimLive.YTickLabel = '';
            app.FigXlimLive.ButtonDownFcn = createCallbackFcn(app, @FigXlimLiveButtonDown, true);
            app.EditField_xlimMax = uieditfield(app.GridLayout_PlotSettings, 'numeric');
            app.EditField_xlimMax.ValueDisplayFormat = '%3.4g';
            app.EditField_xlimMax.ValueChangedFcn = createCallbackFcn(app, @EditField_LiveXMinMaxValueChanged, true);
            app.EditField_xlimMax.Value = 4000;
            app.xlimMax_m1 = uibutton(app.GridLayout_PlotSettings, 'push');
            app.xlimMax_m1.ButtonPushedFcn = createCallbackFcn(app, @xlimMax_m1ButtonPushed, true);
            app.xlimMax_m1.Text = '- 1';
            app.xlimMax_m10 = uibutton(app.GridLayout_PlotSettings, 'push');
            app.xlimMax_m10.ButtonPushedFcn = createCallbackFcn(app, @xlimMax_m10ButtonPushed, true);
            app.xlimMax_m10.Text = '-10';
            app.ResetButton = uibutton(app.GridLayout_PlotSettings, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.xlimMin_m10 = uibutton(app.GridLayout_PlotSettings, 'push');
            app.xlimMin_m10.ButtonPushedFcn = createCallbackFcn(app, @xlimMin_m10ButtonPushed, true);
            app.xlimMin_m10.Text = '-10';
            app.xlimMin_m1 = uibutton(app.GridLayout_PlotSettings, 'push');
            app.xlimMin_m1.ButtonPushedFcn = createCallbackFcn(app, @xlimMin_m1ButtonPushed, true);
            app.xlimMin_m1.Text = '- 1';
            app.EditField_xlimMin = uieditfield(app.GridLayout_PlotSettings, 'numeric');
            app.EditField_xlimMin.ValueDisplayFormat = '%3.4g';
            app.EditField_xlimMin.ValueChangedFcn = createCallbackFcn(app, @EditField_LiveXMinMaxValueChanged, true);
            app.EditField_xlimMin.Value = 400;
            app.xlimMin_p1 = uibutton(app.GridLayout_PlotSettings, 'push');
            app.xlimMin_p1.ButtonPushedFcn = createCallbackFcn(app, @xlimMin_p1ButtonPushed, true);
            app.xlimMin_p1.Text = '+ 1';
            app.xlimMin_p10 = uibutton(app.GridLayout_PlotSettings, 'push');
            app.xlimMin_p10.ButtonPushedFcn = createCallbackFcn(app, @xlimMin_p10ButtonPushed, true);
            app.xlimMin_p10.Text = '+10';
            app.xlimMax_p10 = uibutton(app.GridLayout_PlotSettings, 'push');
            app.xlimMax_p10.ButtonPushedFcn = createCallbackFcn(app, @xlimMax_p10ButtonPushed, true);
            app.xlimMax_p10.Text = '+10';
            app.xlimMax_p1 = uibutton(app.GridLayout_PlotSettings, 'push');
            app.xlimMax_p1.ButtonPushedFcn = createCallbackFcn(app, @xlimMax_p1ButtonPushed, true);
            app.xlimMax_p1.Text = '+ 1';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = LineXlim_demo

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end