classdef SILLS < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        h_fig                           matlab.ui.Figure
        file                            matlab.ui.container.Menu
        wkspnew                         matlab.ui.container.Menu
        wksopen                         matlab.ui.container.Menu
        wkspsave                        matlab.ui.container.Menu
        ExitMenu                        matlab.ui.container.Menu
        settings                        matlab.ui.container.Menu
        input                           matlab.ui.container.Menu
        cps                             matlab.ui.container.Menu
        counts                          matlab.ui.container.Menu
        time_format                     matlab.ui.container.Menu
        drifthhmm                       matlab.ui.container.Menu
        driftpoints                     matlab.ui.container.Menu
        SRMMenu                         matlab.ui.container.Menu
        SRMLoad                         matlab.ui.container.Menu
        SRMdefine                       matlab.ui.container.Menu
        dwelltimes                      matlab.ui.container.Menu
        flicker                         matlab.ui.container.Menu
        automate                        matlab.ui.container.Menu
        autotime                        matlab.ui.container.Menu
        autoint                         matlab.ui.container.Menu
        help                            matlab.ui.container.Menu
        manual                          matlab.ui.container.Menu
        about                           matlab.ui.container.Menu
        reparation                      matlab.ui.container.Menu
        DevMenu                         matlab.ui.container.Menu
        DatadriftTestMenu               matlab.ui.container.Menu
        DatafilterTestMenu              matlab.ui.container.Menu
        Calc_EvaluateTestMenu           matlab.ui.container.Menu
        GenRepTestMenu                  matlab.ui.container.Menu
        Panel                           matlab.ui.container.Panel
        h_currentUNK_sigtotal_out       matlab.ui.control.NumericEditField
        h_currentUNK_mattotal_out       matlab.ui.control.NumericEditField
        h_currentSTD_filename_out       matlab.ui.control.EditField
        FilenameEditFieldLabel          matlab.ui.control.Label
        h_currentUNK_filename_out       matlab.ui.control.EditField
        FilenameEditFieldLabel_2        matlab.ui.control.Label
        LASAButton                      matlab.ui.control.Button
        h_currentSTD_bgfrom_out         matlab.ui.control.NumericEditField
        fromEditFieldLabel              matlab.ui.control.Label
        h_currentSTD_sigfrom_out        matlab.ui.control.NumericEditField
        h_currentSTD_bgto_out           matlab.ui.control.NumericEditField
        toEditFieldLabel                matlab.ui.control.Label
        h_currentSTD_bgtotal_out        matlab.ui.control.NumericEditField
        totalEditFieldLabel             matlab.ui.control.Label
        h_currentSTD_driftpoints_popup  matlab.ui.control.DropDown
        h_currentSTD_sigto_out          matlab.ui.control.NumericEditField
        h_currentSTD_sigtotal_out       matlab.ui.control.NumericEditField
        BkgdLabel                       matlab.ui.control.Label
        SignalLabel                     matlab.ui.control.Label
        h_currentUNK_bgtotal_out        matlab.ui.control.NumericEditField
        h_currentUNK_mat1total_out      matlab.ui.control.NumericEditField
        h_currentUNK_mat2total_out      matlab.ui.control.NumericEditField
        h_currentUNK_comp1total_out     matlab.ui.control.NumericEditField
        h_currentUNK_comp2total_out     matlab.ui.control.NumericEditField
        h_currentUNK_comp3total_out     matlab.ui.control.NumericEditField
        h_currentUNK_bgto_out           matlab.ui.control.NumericEditField
        h_currentUNK_bgfrom_out         matlab.ui.control.NumericEditField
        h_currentUNK_mat1to_out         matlab.ui.control.NumericEditField
        h_currentUNK_mat1from_out       matlab.ui.control.NumericEditField
        h_currentUNK_mat2to_out         matlab.ui.control.NumericEditField
        h_currentUNK_mat2from_out       matlab.ui.control.NumericEditField
        h_currentUNK_comp1to_out        matlab.ui.control.NumericEditField
        h_currentUNK_comp1from_out      matlab.ui.control.NumericEditField
        h_currentUNK_comp2to_out        matlab.ui.control.NumericEditField
        h_currentUNK_comp3to_out        matlab.ui.control.NumericEditField
        h_currentUNK_comp2from_out      matlab.ui.control.NumericEditField
        h_currentUNK_comp3from_out      matlab.ui.control.NumericEditField
        GridLayout                      matlab.ui.container.GridLayout
        ImportModuleButton              matlab.ui.control.Button
        CopyStandardButton              matlab.ui.control.Button
        HHMMLabel                       matlab.ui.control.Label
        h_currentSTD_drifthh_edit       matlab.ui.control.NumericEditField
        h_currentSTD_driftmm_edit       matlab.ui.control.NumericEditField
        UnknownSettingsLabel            matlab.ui.control.Label
        StandardSettingsLabel           matlab.ui.control.Label
        h_CALC_manager                  matlab.ui.control.Button
        h_currentUNK_popup              matlab.ui.control.ListBox
        h_currentSTD_popup              matlab.ui.control.ListBox
        h_currentSTD_SRM_out            matlab.ui.control.DropDown
        h_unk_copy_button               matlab.ui.control.Button
        h_unk_delete_button             matlab.ui.control.Button
        h_std_delete_button             matlab.ui.control.Button
        h_unk_plot_button               matlab.ui.control.Button
        h_std_plot_button               matlab.ui.control.Button
        h_load_unk_button               matlab.ui.control.Button
        h_load_std_button               matlab.ui.control.Button
        STD_Plt                         matlab.ui.control.UIAxes
        UNK_Plt                         matlab.ui.control.UIAxes
    end


    properties (Access = public)

        A
        SMAN
        STD
        UNK
        SRM
        REPORT
        LOGDAT
        SIGDAT
        LASA
    end

    properties (Access = private)
        hLineToDrag
        hVerticalLines % Description
        hVerticalLinesBounds % Description
    end


    methods (Access = public)

        % update functions
        function h_currentSTD_popup_update(app)
            app.h_currentSTD_popup.Items=app.A.STDPOPUPLIST;
            app.h_currentSTD_popup.ItemsData=1:numel(app.A.STDPOPUPLIST);
            if isfield(app.A, 'STD_active') && not(isempty(app.A.STD_active)) && app.A.STD_active<0 %shouldn't this be >?
                app.h_currentSTD_popup.Value=app.A.STD_active;
            end
        end

        function h_currentUNK_popup_update(app)
            app.h_currentUNK_popup.Items=app.A.UNKPOPUPLIST;
            app.h_currentUNK_popup.ItemsData=1:numel(app.A.UNKPOPUPLIST);
            if isfield(app.A, 'UNK_active') && not(isempty(app.A.UNK_active)) && app.A.UNK_active>0
                app.h_currentUNK_popup.Value=app.A.UNK_active;
            end

        end

        function SillsGuiUpdate(app)

            h_currentSTD_popup_update(app)
            h_currentUNK_popup_update(app)
            update_SRM_list_fun(app)
            app.A.UNK_num = numel({app.UNK.data});
            app.A.STD_num = numel({app.STD.data});

            figure(app.h_fig); % make SILLS Control Panel the current figure


        end


        % SRM list initalizing functions
        function load_SRM_NG(app,SRM_file,update_list)
            try
                B = importdata(SRM_file);
                srm_tmp.data = B.data;
                srm_tmp.textdata = B.textdata;
                if isfield(B,'rowheaders')
                    srm_tmp.rowheaders = B.rowheaders;
                else
                    srm_tmp.rowheaders = srm_tmp.textdata;
                end
                srm_tmp.fileinfo = dir(SRM_file);
                srm_tmp.name = srm_tmp.fileinfo.name;

                if numel({app.SRM.name})==1 && isempty(app.SRM.name)
                    app.SRM(1)=srm_tmp;
                else
                    app.SRM(end+1)=srm_tmp;
                end
                app.A.userSRMfile=srm_tmp.fileinfo.name;
                if update_list==true
                    update_SRM_list_fun(app)
                end
            catch ME
                DisplayErrorNG(app,ME,'load_SRM_NG')
                return
            end
        end

        % Loads the SRM files at startup
        function SRM_load_startup(app)

            if isdeployed
                disp("fix here filepath SRM Files")
                filepath='';
            else
                [filepath,name,ext] = fileparts(which('SILLS.mlapp'));
            end
            SRM_dir = dir(fullfile(filepath, 'SRM Files'));
            SRM_dir=SRM_dir(~ismember({SRM_dir.name},{'.','..'}));

            for n=1:numel({SRM_dir.name})
                try
                    load_SRM_NG(app,fullfile(SRM_dir(n).folder,SRM_dir(n).name),false)
                catch ME
                    DisplayErrorNG(app,ME,'SRM_load_startup')

                end
            end
            update_SRM_list_fun(app)
            app.h_currentSTD_SRM_out.Value='Assign SRM';

        end

        % Populates the SRM list
        function update_SRM_list_fun(app)
            app.A.SRMPOPUPLIST = {app.SRM.name};
            app.h_currentSTD_SRM_out.Items=['Assign SRM',app.A.SRMPOPUPLIST]; % update the SRM list in the popup window
            try
                app.h_currentSTD_SRM_out.Value=app.A.userSRMfile;
            catch ME
                DisplayErrorNG(app,ME,'update_SRM_list_fun')
            end
        end

        function results = import_STD_public(app, data,colheaders,SRM,update_state,hh,mm)
            %function results = import_STD_public(app, STD_tmp)

            fileinfo='';
            %textdata

            STD_tmp.data=data;

            STD_tmp.textdata=colheaders;

            STD_tmp.colheaders=colheaders;
            STD_tmp.legendentries=colheaders;


            STD_tmp.fileinfo=fileinfo;
            STD_tmp.num_elements=size(colheaders(2:end),2);

            STD_tmp.hh=hh;
            STD_tmp.mm=mm;

            STD_tmp.SRM=SRM;
            STD_tmp.fileinfo='';

            STD_tmp.bgwindow = [];

            STD_tmp.sigwindow = [];

            STD_tmp.timepoint = 1;
            STD_tmp.fileinfo.ext = ext1;

            STD_tmp.order_opened = app.A.d;

            STD_tmp.figure_state = 'shut';
            if not(isfield(STD_tmp,'spikestatus')) % && NOT checked for allowed string
                STD_tmp.spikestatus = 'undone';
            end

            app.STD(end+1)=STD_tmp;

            app.A.STD_active = size(app.STD);
            app.A.STD_active = app.A.STD_active(1);
            app.A.STDPOPUPLIST(app.A.STD_active,1) = {app.STD(app.A.STD_active,1).fileinfo.name};

            app.A.d = app.A.d + 1; %advance the counter for the number of standards opened (irrespective of number deleted)

            if update_state==true
                SillsGuiUpdate(app)
            end
        end


        % Data import from Import Module functions

        %as far as I remember, these work
        function public_import_fullSTD(app,STD_tmp,update_state)

            fns = fieldnames(STD_tmp);

            for id2i = 1:numel(STD_tmp)

                if isempty(app.STD(1).data)
                    id=1;
                else
                    id=numel(app.STD)+1;
                end

                for n=1:numel(fns)
                    app.STD(id).(char(fns(n))) = STD_tmp(id2i).(char(fns(n)));
                end

                app.A.STDPOPUPLIST(id) = {app.STD(id).fileinfo.name};
                app.A.d = app.A.d + 1; %advance the counter for the number of standards opened (irrespective of number deleted)

                %     app.STD(end+1)=STD_tmp;
            end

            app.A.STD_active = numel(app.STD);
            app.A.STD_active = app.A.STD_active(1);


            if update_state == true
                SillsGuiUpdate(app)
            end

        end

        function public_import_fullUNK(app,UNK_tmp,update_state)

            fns = fieldnames(UNK_tmp);

            for id2i = 1:numel(UNK_tmp)

                if isempty(app.UNK(1).data)
                    id=1;
                else
                    id=numel(app.UNK)+1;
                end

                for n=1:numel(fns)
                    app.UNK(id).(char(fns(n))) = UNK_tmp(id2i).(char(fns(n)));
                end

                app.A.UNKPOPUPLIST(id) = {app.UNK(id).fileinfo.name};
                app.A.d = app.A.d + 1; %advance the counter for the number of standards opened (irrespective of number deleted)

            end

            if update_state == true
                SillsGuiUpdate(app)
            end
        end

        function public_import_fullA(app, A_tmp)

            fns = fields(A_tmp);
            % this is done so that only fields with the same name get
            % replaced
            for i = 1:numel(fns)
                app.A.(fns{i}) = A_tmp.(fns{i});
            end

        end

        function DisplayErrorNG(app,ME,WhichOperation)
            disp(' ')
            disp('%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%')
            disp('%%%%%%%%%%%%%% Error %%%%%%%%%%%%%%%%')
            disp('%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%')
            disp(['Error occured during: ' WhichOperation])
            % for n=1
            for n=numel(ME.stack):-1:1
                disp('Error in file:')
                disp(ME.stack(n).file)
                disp(['Error in line: ',num2str(ME.stack(n).line)])
            end
            disp(['Error in messsage: ' ME.message])
            disp(' ')
            disp('%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%')
            disp(' ')

        end


        % functions for the intervals
        function update_std_plot_fun(app,ax2plot,update_lines)
            if not(exist('ax2plot','var'))
                ax2plot=app.STD_Plt;
            end
            if not(exist('update_lines','var'))
                update_lines=false;
            end
            %plot the data
            y = app.STD(app.A.STD_active).data(:,2:end);
            x = app.STD(app.A.STD_active).data(:,1);

            %plot the data
            plot(ax2plot, x, y, 'linewidth', 2)

            % Check and plot the background intervals if not empty
            if ~isempty(app.STD(app.A.STD_active).bgwindow)
                bg_start = app.STD(app.A.STD_active).bgwindow(1); % These should be adjustable by moving the xlines
                bg_end = app.STD(app.A.STD_active).bgwindow(2);
                if update_lines==true
                    app.hVerticalLines(1).xl = xline(ax2plot, bg_start, 'linewidth', 2, 'Color', [0.5 0.5 0.5],'Tag','STD_bgwindow','hittest','off'); % Background interval start
                    app.hVerticalLines(2).xl = xline(ax2plot, bg_end, 'linewidth', 2, 'Color', [0.5 0.5 0.5],'Tag','STD_bgwindow','hittest','off'); % Background interval end

                else
                    xline(ax2plot, bg_start, 'linewidth', 2, 'Color', [0.5 0.5 0.5]); % Background interval start
                    xline(ax2plot, bg_end, 'linewidth', 2, 'Color', [0.5 0.5 0.5]); % Background interval end
                end
            end

            % Check and plot the signal intervals if not empty
            if ~isempty(app.STD(app.A.STD_active).sigwindow)
                sig_start = app.STD(app.A.STD_active).sigwindow(1);
                sig_end = app.STD(app.A.STD_active).sigwindow(2);

                if update_lines==true
                    app.hVerticalLines(3).xl =    xline(ax2plot, sig_start, 'linewidth', 2, 'Color', "green",'Tag','STD_sigwindow','hittest','off'); % Signal interval start
                    app.hVerticalLines(4).xl =  xline(ax2plot, sig_end, 'linewidth', 2, 'Color', "green",'Tag','STD_sigwindow','hittest','off'); % Signal interval end
                else
                    xline(ax2plot, sig_start, 'linewidth', 2, 'Color', "green"); % Signal interval start
                    xline(ax2plot, sig_end, 'linewidth', 2, 'Color', "green"); % Signal interval end
                end
            end

            % Draw a grey rectangle between the lines
            %ylimits = ylim(ax2plot);
            %rectangle(ax2plot, 'Position', [bg_start, ylimits(1), bg_end-bg_start, ylimits(2)-ylimits(1)], 'FaceColor', [0.25, 0.25, 0.25, 0.25], 'EdgeColor', 'none');
            %rectangle(ax2plot, 'Position', [sig_start, ylimits(1), sig_end-sig_start, ylimits(2)-ylimits(1)], 'FaceColor', [0.25, 0.25, 0.25, 0.25], 'EdgeColor', 'none');

            xlabel(ax2plot,'Time [s]')
            ylabel(ax2plot,'Counts')
            ax2plot.YScale='log';
            ax2plot.XLim=[min(x) max(x)];
            ylim(ax2plot,'auto');
            update_areas(app,'STD')
        end

        function update_unk_plot_fun(app,ax2plot,update_lines)
            if not(exist('ax2plot','var'))
                ax2plot=app.UNK_Plt;
            end
            if not(exist('update_lines','var'))
                update_lines=false;
            end

            % Plot the data
            y = app.UNK(app.A.UNK_active).data(:, 2:end);
            x = app.UNK(app.A.UNK_active).data(:, 1);

            % Plot the data
            plot(ax2plot, x, y, 'linewidth', 2)

            % Check and plot the background intervals if not empty


            if ~isempty(app.UNK(app.A.UNK_active).bgwindow)
                bg_start = app.UNK(app.A.UNK_active).bgwindow(1); % These should be adjustable by moving the xlines
                bg_end = app.UNK(app.A.UNK_active).bgwindow(2);
                if update_lines==true
                    app.hVerticalLines(5).xl = xline(ax2plot, bg_start, 'linewidth', 2, 'Color', [0.5 0.5 0.5],'Tag','UNK_bgwindow','hittest','off'); % Background interval start
                    app.hVerticalLines(6).xl = xline(ax2plot, bg_end, 'linewidth', 2, 'Color', [0.5 0.5 0.5],'Tag','UNK_bgwindow','hittest','off'); % Background interval end
                else
                    xline(ax2plot, bg_start, 'linewidth', 2, 'Color', [0.5 0.5 0.5]); % Background interval start
                    xline(ax2plot, bg_end, 'linewidth', 2, 'Color', [0.5 0.5 0.5]); % Background interval end

                end
            end


            if ~isempty(app.UNK(app.A.UNK_active).mat1window)
                mat1_start = app.UNK(app.A.UNK_active).mat1window(1); % These should be adjustable by moving the xlines
                mat1_end = app.UNK(app.A.UNK_active).mat1window(2);
                if update_lines==true
                    app.hVerticalLines(7).xl = xline(ax2plot, mat1_start, 'linewidth', 2, 'Color', "green",'Tag','UNK_mat1window','hittest','off'); % Background interval start
                    app.hVerticalLines(8).xl = xline(ax2plot, mat1_end, 'linewidth', 2, 'Color', "green",'Tag','UNK_mat1window','hittest','off'); % Background interval end
                else
                    xline(ax2plot, mat1_start, 'linewidth', 2, 'Color', "green"); % Background interval start
                    xline(ax2plot, mat1_end, 'linewidth', 2, 'Color', "green"); % Background interval end
                end
            end


            if ~isempty(app.UNK(app.A.UNK_active).mat2window)
                mat2_start = app.UNK(app.A.UNK_active).mat2window(1); % These should be adjustable by moving the xlines
                mat2_end = app.UNK(app.A.UNK_active).mat2window(2);
                if update_lines==true
                    app.hVerticalLines(9).xl = xline(ax2plot, mat2_start, 'linewidth', 2, 'Color', "green",'Tag','UNK_mat2window','hittest','off'); % Background interval start
                    app.hVerticalLines(10).xl = xline(ax2plot, mat2_end, 'linewidth', 2, 'Color', "green",'Tag','UNK_mat2window','hittest','off'); % Background interval end
                else
                    xline(ax2plot, mat2_start, 'linewidth', 2, 'Color', "green"); % Background interval start
                    xline(ax2plot, mat2_end, 'linewidth', 2, 'Color', "green"); % Background interval end
                end
            end


            % Check and plot the signal intervals if not empty
            if ~isempty(app.UNK(app.A.UNK_active).comp1window)
                comp1_start = app.UNK(app.A.UNK_active).comp1window(1);
                comp1_end = app.UNK(app.A.UNK_active).comp1window(2);
                if update_lines==true
                    app.hVerticalLines(11).xl =   xline(ax2plot, comp1_start, 'linewidth', 2, 'Color', "blue",'Tag','UNK_comp1window','hittest','off'); % Signal interval start
                    app.hVerticalLines(12).xl =          xline(ax2plot, comp1_end, 'linewidth', 2, 'Color', "blue",'Tag','UNK_comp1window','hittest','off'); % Signal interval end
                else
                    xline(ax2plot, comp1_start, 'linewidth', 2, 'Color', "blue"); % Signal interval start
                    xline(ax2plot, comp1_end, 'linewidth', 2, 'Color', "blue"); % Signal interval end
                end
            end

            if ~isempty(app.UNK(app.A.UNK_active).comp2window)
                comp2_start = app.UNK(app.A.UNK_active).comp2window(1);
                comp2_end = app.UNK(app.A.UNK_active).comp2window(2);
                if update_lines==true
                    app.hVerticalLines(13).xl =   xline(ax2plot, comp2_start, 'linewidth', 2, 'Color', "blue",'Tag','UNK_comp2window','hittest','off'); % Signal interval start
                    app.hVerticalLines(14).xl =          xline(ax2plot, comp2_end, 'linewidth', 2, 'Color', "blue",'Tag','UNK_comp2window','hittest','off'); % Signal interval end
                else
                    xline(ax2plot, comp2_start, 'linewidth', 2, 'Color', "blue"); % Signal interval start
                    xline(ax2plot, comp2_end, 'linewidth', 2, 'Color', "blue"); % Signal interval end
                end
            end

            if ~isempty(app.UNK(app.A.UNK_active).comp3window)
                comp3_start = app.UNK(app.A.UNK_active).comp3window(1);
                comp3_end = app.UNK(app.A.UNK_active).comp3window(2);
                if update_lines==true
                    app.hVerticalLines(15).xl =   xline(ax2plot, comp3_start, 'linewidth', 2, 'Color', "blue",'Tag','UNK_comp3window','hittest','off'); % Signal interval start
                    app.hVerticalLines(16).xl =          xline(ax2plot, comp3_end, 'linewidth', 2, 'Color', "blue",'Tag','UNK_comp3window','hittest','off'); % Signal interval end
                else
                    xline(ax2plot, comp3_start, 'linewidth', 2, 'Color', "blue"); % Signal interval start
                    xline(ax2plot, comp3_end, 'linewidth', 2, 'Color', "blue"); % Signal interval end
                end
            end

            xlabel(ax2plot, 'Time [s]')
            ylabel(ax2plot, 'Counts')
            ax2plot.YScale = 'log';
            xlabel(ax2plot,'Time [s]')
            ylabel(ax2plot,'Counts')
            ax2plot.YScale='log';
            ax2plot.XLim=[min(x) max(x)];
            ylim(ax2plot,'auto');

            update_areas(app,'UNK')
        end
    end

    methods (Access = private)

        % not sure if needed
        function stdfile_multiload(app)
            for i = 1:length(app.A.stdfile)

                app.A.stdfullfilename = strcat(app.A.sillspath,app.A.stdfile(i));
                app.A.stdfullfilename = char(app.A.stdfullfilename);
                [~,~,ext1]=fileparts(app.A.stdfullfilename);

                if strcmp(ext1,'.FIN2')
                    STDIMP = importdata(app.A.stdfullfilename,',',8);%Imports Glitter output from Element XR
                    dateFIN1 = importdata(app.A.stdfullfilename);
                    dateFIN2 = dateFIN1.textdata(2,1);
                    dateFIN = dateFIN2{1};
                elseif strcmp(ext1,'.TXT')  %Imports TXT-files form the Element XR
                    STDIMP=convert_NG(app.A.stdfullfilename);        %old convert_std;
                else
                    STDIMP = importdata(app.A.stdfullfilename);
                end

                %Remove zero entries after last time step
                STDIMP.data(STDIMP.data(:,1)==0,:) = []; %NEW
                %% OLD BLOCK
                %     times = STDIMP.data(:,1);
                %     searchdestroy = find(times==0);
                %     if ~isempty(searchdestroy)
                %         searchdestroy(1) = [];
                %         STDIMP.data(times==0,:) = [];
                %     end
                %%

                %% @S are these variables needed in the data structure?
                if app.A.k > 0 %if there is one or more unknowns already in the app.UNK array

                    STDIMP_num = size(STDIMP.colheaders(2:end));
                    STDIMP_num = STDIMP_num(2);

                    app.A.UNK_active = size(app.UNK);
                    app.A.UNK_active = app.A.UNK_active(1);

                    if app.UNK(app.A.UNK_active,1).num_elements ~= STDIMP_num || sum(strcmp(app.UNK(app.A.UNK_active,1).colheaders(2:end),STDIMP.colheaders(2:end))) < app.UNK(app.A.UNK_active).num_elements %if there are a different number of elements
                        error_message='Element lists do not match. Please load a new standard file.';
                        uialert(app.h_fig,error_message,'SILLS Error Message');
                        return
                    end
                end

                if app.A.d > 0 %if there is one or more standards already in the app.STD array

                    %test for unmatching element lists between this standard and ones loaded
                    %previously

                    STDIMP_num = size(STDIMP.colheaders(2:end));
                    STDIMP_num = STDIMP_num(2);

                    app.A.STD_active = size(app.STD);
                    app.A.STD_active = app.A.STD_active(1);

                    if app.STD(app.A.STD_active,1).num_elements ~= STDIMP_num || sum(strcmp(app.STD(app.A.STD_active,1).colheaders(2:end),STDIMP.colheaders(2:end))) < app.STD(app.A.STD_active).num_elements
                        %if there are a different number of elements
                        error_message='Element lists do not match. Please load a new standard file.';
                        uialert(app.h_fig,error_message,'SILLS Error Message');
                        return
                    end

                    % populate app.STD(STD_num), i.e. the most recent standard
                    app.STD(app.A.STD_active + 1).data = STDIMP.data;
                    app.STD(app.A.STD_active + 1).textdata = STDIMP.textdata;
                    app.STD(app.A.STD_active + 1).colheaders = STDIMP.colheaders;
                    app.STD(app.A.STD_active + 1).fileinfo = dir(app.A.stdfullfilename);
                    app.STD(app.A.STD_active + 1).num_elements = size(app.STD(app.A.STD_active,1).colheaders(2:end));
                    app.STD(app.A.STD_active + 1).num_elements = app.STD(app.A.STD_active + 1).num_elements(2);
                    app.STD(app.A.STD_active + 1).bgwindow = [];
                    app.STD(app.A.STD_active + 1).sigwindow = [];
                    app.STD(app.A.STD_active + 1).timepoint = 1;
                    app.STD(app.A.STD_active + 1).fileinfo.ext = ext1;
                    if strcmp(ext1,'.FIN2')
                        app.STD(app.A.STD_active +1,1).fileinfo.date = dateFIN;
                    end

                    app.A.MC = app.h_currentSTD_SRM_out.Value;
                    app.STD(app.A.STD_active + 1).SRM = app.A.MC;

                    app.A.d = app.A.d + 1; %advance the counter for the number of standards opened (irrespective of number deleted)
                    app.STD(app.A.STD_active + 1).order_opened = app.A.d;

                    app.STD(app.A.STD_active + 1).figure_state = 'shut';
                    app.STD(app.A.STD_active + 1).spikestatus = 'undone';
                    app.A.STD_active = size(app.STD);
                    app.A.STD_active = app.A.STD_active(1);
                    app.A.STDPOPUPLIST(app.A.STD_active,1) = {app.STD(app.A.STD_active,1).fileinfo.name};

                    %% new datestr(STDIMP.data(1,1),'hh:mm')
                    try
                        hhmm=char((duration(0,0,STDIMP.data(1,1),'format','hh:mm')));
                        hh=hhmm(1:2);
                        mm=hhmm(4:5);
                    catch
                        hh='00';
                        mm='00';
                    end

                    app.STD(app.A.STD_active,1).hh=hh;
                    app.STD(app.A.STD_active,1).mm=mm;
                elseif app.A.d == 0 %i.e. first time a standard has been loaded

                    % populate app.STD(1)
                    app.STD(1).data = STDIMP.data;
                    app.STD(1).textdata = STDIMP.textdata;
                    app.STD(1).colheaders = STDIMP.colheaders;
                    app.STD(1).fileinfo = dir(app.A.stdfullfilename);
                    app.STD(1).num_elements = size(app.STD(1).colheaders(2:end));
                    app.STD(1).num_elements = app.STD(1).num_elements(2);
                    app.STD(1).bgwindow = [];
                    app.STD(1).sigwindow = [];
                    app.STD(1).timepoint = 1;
                    app.STD(1).fileinfo.ext = ext1;
                    if strcmp(ext1,'.FIN2')
                        app.STD(1).fileinfo.date = dateFIN;
                    end

                    app.STD(1).SRM = app.h_currentSTD_SRM_out.Value;
                    set(app.h_currentSTD_SRM_out,'Visible','on'); %make the popuplist visible in the 'Data for selected Standard' window

                    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                    %Make the appropriate drift setting uicontrols visible in the
                    % SILLS Control Panel

                    if strcmp(app.A.timeformat,'hhmm')==1
                        set(app.h_currentSTD_driftpoints_popup,'Visible','off');
                        set(app.h_currentSTD_drifthh_edit,'Visible','on');
                        %  set(app.h_currentSTD_driftcolon,'Visible','on');                        %@S missing

                        set(app.h_currentSTD_driftmm_edit,'Visible','on');
                    elseif strcmp(app.A.timeformat,'integer_points')==1
                        set(app.h_currentSTD_driftpoints_popup,'Visible','on');
                        set(app.h_currentSTD_drifthh_edit,'Visible','off');
                        %set(app.h_currentSTD_driftcolon,'Visible','off');
                        %@S missing
                        set(app.h_currentSTD_driftmm_edit,'Visible','off');
                    end

                    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%


                    app.A.d = 1; %counter for the number of standards opened (irrespective of number deleted)
                    app.A.D = 0; %number of open app.STD windows

                    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                    %clear these in case former standards or unknowns were loaded that had
                    %a different isotope list
                    app.A.ISOTOPE_list = {};
                    app.A.DT_VALUES = [];
                    app.A.ELEMENT_list = {};

                    app.A.ISOTOPE_list = app.STD(1).colheaders(2:end);
                    app.A.ISOTOPE_num = size(app.A.ISOTOPE_list);
                    app.A.ISOTOPE_num = app.A.ISOTOPE_num(2);
                    app.A.DT_VALUES = 0.01 * ones(app.A.ISOTOPE_num,1);

                    %create a list of elements analysed

                    isotope = char(app.A.ISOTOPE_list); %convert isotopes into a character array
                    iselement = isletter(isotope);  %search for letters within the 'isotope' array
                    element = iselement.*isotope;
                    element2 = char(element);
                    for c = 1:app.A.ISOTOPE_num
                        app.A.ELEMENT_list(c) = {element2(c,:)};
                    end
                    app.A.ELEMENT_list = deblank(app.A.ELEMENT_list);
                    app.A.ELEMENT_num = size(app.A.ELEMENT_list);
                    app.A.ELEMENT_num = app.A.ELEMENT_num(2);
                    clear isotope iselement element element2
                    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

                    app.STD(1).order_opened = app.A.d;
                    app.STD(1).figure_state = 'shut';
                    app.STD(1).spikestatus = 'undone';

                    app.A.STD_active = 1; %total number of standards
                    app.A.STDPOPUPLIST(1,1) = {app.STD(1).fileinfo.name};

                    app.A.D = 0; %number of open app.STD windows

                    %% new string(duration(0,0,STDIMP.data(1,1),'format','hh:mm'))
                    try
                        hhmm=char((duration(0,0,STDIMP.data(1,1),'format','hh:mm')));
                        hh=hhmm(1:2);
                        mm=hhmm(4:5);
                    catch
                        hh='00';
                        mm='00';
                    end
                    app.STD(1).hh=hh;
                    app.STD(1).mm=mm;

                end
            end

            app.A.DC = app.A.STD_active; %by default the current unknown is the last one loaded

            %% old @S i think this is the figure popup which plots unknowns and can go
            % app.A.STDfigs_open = {app.STD.figure_state};
            % app.A.STDfigs_open = strcmp(app.A.STDfigs_open,'open');
            %
            % STDRESETX = ['if strcmp(get(gca,''tag''),''app.STD.handles.h_stdplot'');'...
            %                  'set(gca,''XLim'',app.STD(app.A.DC).XLim_orig,''YLim'',app.STD(app.A.DC).YLim_orig);'...
            %              'end'];
            %
            %
            % set(app.h_currentSTD_popup,'string',app.A.STDPOPUPLIST,'value',app.A.STD_active);
            % SILLSFIG_UPDATE

        end


        function unkfile_multiload(app)

            unkfilenum = length(app.A.unkfile);

            for i = 1:unkfilenum

                app.A.unkfullfilename = strcat(app.A.sillspath,app.A.unkfile(i));
                app.A.unkfullfilename = char(app.A.unkfullfilename);
                [te1,te2,ext1]=fileparts(app.A.unkfullfilename);
                if strcmp(ext1,'.FIN2')
                    UNKIMP = importdata(app.A.unkfullfilename,',',8); %Import Glitter output from Element XR
                    dateFIN1 = importdata(app.A.unkfullfilename);
                    dateFIN2 = dateFIN1.textdata(2,1);
                    dateFIN = dateFIN2{1};
                    clear dateFIN1 dateFIN2
                elseif strcmp(ext1,'.TXT')  %Imports TXT-files form the Element XR
                    convert;
                else
                    UNKIMP = importdata(app.A.unkfullfilename);
                end

                %Remove zero entries after last time step
                %Added in 1.0.5 changed in 1.1.1
                times = UNKIMP.data(:,1);
                searchdestroy = find(times==0);
                if ~isempty(searchdestroy)
                    searchdestroy(1) = [];
                    UNKIMP.data(searchdestroy,:) = [];
                end
                clear times searchdestroy

                if app.A.d > 0 %if there is one or more standards already in the STD array

                    %test for unmatching element lists between this unknown and previously
                    %loaded standards

                    UNKIMP_num = size(UNKIMP.colheaders(2:end));
                    UNKIMP_num = UNKIMP_num(2);

                    app.A.STD_active = size(app.STD);
                    app.A.STD_active = app.A.STD_active(1);

                    if app.STD(app.A.STD_active,1).num_elements ~= UNKIMP_num %if there are a different number of elements
                        msgbox('Element lists do not match. Please load a new file.','SILLS Error Message','error');
                        return
                    else
                        temp = strcmp(app.STD(app.A.STD_active,1).colheaders(2:end),UNKIMP.colheaders(2:end)); %compare the new element list with the one originally read
                        if sum(temp) < app.STD(app.A.STD_active).num_elements
                            msgbox('Element lists do not match. Please load a new file.','SILLS Error Message','error');
                            clear temp
                            return
                        end
                        clear temp
                    end
                end


                if app.A.k < 0 %i.e. if there are already one or more unknowns in the app.UNK array (remember k counts in the negative direction)

                    %test for unmatching element lists between this standard and ones loaded
                    %previously

                    UNKIMP_num = size(UNKIMP.colheaders(2:end));
                    UNKIMP_num = UNKIMP_num(2);

                    app.A.UNK_active = size(app.UNK);
                    app.A.UNK_active = app.A.UNK_active(1);

                    if app.UNK(app.A.UNK_active,1).num_elements ~= UNKIMP_num
                        msgbox('Element lists do not match. Please load a new standard file.','SILLS Error Message','error');
                        return
                    else
                        temp = strcmp(app.UNK(app.A.UNK_active,1).colheaders(2:end),UNKIMP.colheaders(2:end)); %compare the new element list with the one originally read
                        if sum(temp) < app.UNK(app.A.UNK_active).num_elements
                            msgbox('Element lists do not match! Please load a new standard file.','SILLS Error Message','error');
                            clear temp
                            return
                        end
                        clear temp
                    end

                    % populate app.UNK(UNK_num), i.e. the most recent unknown
                    app.UNK(app.A.UNK_active + 1,1).data = UNKIMP.data;
                    app.UNK(app.A.UNK_active + 1,1).textdata = UNKIMP.textdata;
                    app.UNK(app.A.UNK_active + 1,1).colheaders = UNKIMP.colheaders;
                    app.UNK(app.A.UNK_active + 1,1).fileinfo = dir(app.A.unkfullfilename);
                    app.UNK(app.A.UNK_active + 1,1).num_elements = size(app.UNK(app.A.UNK_active,1).colheaders(2:end));
                    app.UNK(app.A.UNK_active + 1,1).num_elements = app.UNK(app.A.UNK_active + 1,1).num_elements(2);
                    app.UNK(app.A.UNK_active + 1,1).bgwindow = [];
                    app.UNK(app.A.UNK_active + 1,1).mat1window = [];
                    app.UNK(app.A.UNK_active + 1,1).mat2window = [];
                    app.UNK(app.A.UNK_active + 1,1).mattotal = [];
                    app.UNK(app.A.UNK_active + 1,1).comp1window = [];
                    app.UNK(app.A.UNK_active + 1,1).comp2window = [];
                    app.UNK(app.A.UNK_active + 1,1).comp3window = [];
                    app.UNK(app.A.UNK_active + 1,1).sigtotal = [];
                    app.UNK(app.A.UNK_active + 1,1).MAT_corrtype = 1;
                    app.UNK(app.A.UNK_active + 1,1).MATcorrfile = app.A.UNK_active+1;
                    app.UNK(app.A.UNK_active + 1,1).MATQIS = 1;
                    app.UNK(app.A.UNK_active + 1,1).MATQISiso = 1;
                    app.UNK(app.A.UNK_active + 1,1).MATQISox = 1;
                    app.UNK(app.A.UNK_active + 1,1).MATunit = 1;
                    app.UNK(app.A.UNK_active + 1,1).SIG_quanttype = 1;
                    app.UNK(app.A.UNK_active + 1,1).SIGQIS = 1;
                    app.UNK(app.A.UNK_active + 1,1).timepoint = 1;
                    app.UNK(app.A.UNK_active + 1,1).SALT = zeros(app.A.ELEMENT_num,1); %whether isotopes are included in a salt correction or not
                    app.UNK(app.A.UNK_active + 1,1).SALT_mass_balance_factor = 0.5; %Added in 1.0.6
                    app.UNK(app.A.UNK_active +1,1).Info = [];
                    app.UNK(app.A.UNK_active +1,1).SIG_constraint1 = 1;
                    app.UNK(app.A.UNK_active +1,1).SIG_constraint2 = 1;
                    app.UNK(app.A.UNK_active +1,1).SIGQIS1 = app.UNK(app.A.UNK_active,1).SIGQIS1;
                    app.UNK(app.A.UNK_active +1,1).SIGQIS1iso = app.UNK(app.A.UNK_active,1).SIGQIS1iso;
                    app.UNK(app.A.UNK_active +1,1).SIGQIS1ox = app.UNK(app.A.UNK_active,1).SIGQIS1ox;
                    app.UNK(app.A.UNK_active +1,1).SIG1unit = app.UNK(app.A.UNK_active,1).SIG1unit;
                    app.UNK(app.A.UNK_active +1,1).SIGQIS2 = app.UNK(app.A.UNK_active,1).SIGQIS2;
                    app.UNK(app.A.UNK_active +1,1).SIGQIS2iso = app.UNK(app.A.UNK_active,1).SIGQIS2iso;
                    app.UNK(app.A.UNK_active +1,1).SIGQIS2ox = app.UNK(app.A.UNK_active,1).SIGQIS2ox;
                    app.UNK(app.A.UNK_active +1,1).SIG2unit = app.UNK(app.A.UNK_active,1).SIG2unit;
                    app.UNK(app.A.UNK_active +1,1).SIG_tracer = 1;
                    app.UNK(app.A.UNK_active +1,1).fileinfo.ext = ext1;
                    if strcmp(ext1,'.FIN2')
                        app.UNK(app.A.UNK_active +1,1).fileinfo.date = dateFIN;
                    end

                    app.A.k = app.A.k - 1; %advance the counter for the number of unknowns opened (irrespective of number deleted); remember this counts in the neg. direction
                    app.UNK(app.A.UNK_active + 1,1).order_opened = app.A.k; %remember that k is a negative number

                    app.UNK(app.A.UNK_active + 1,1).figure_state = 'shut';
                    app.UNK(app.A.UNK_active + 1,1).spikestatus = 'undone';
                    app.A.UNK_active = size(app.UNK);
                    app.A.UNK_active = app.A.UNK_active(1);
                    app.A.UNKPOPUPLIST(app.A.UNK_active,1) = {app.UNK(app.A.UNK_active,1).fileinfo.name};

                elseif app.A.k == 0 %i.e. first time an unknown has been loaded

                    % populate app.UNK(1)
                    app.UNK(1).data = UNKIMP.data;
                    app.UNK(1).textdata = UNKIMP.textdata;
                    app.UNK(1).colheaders = UNKIMP.colheaders;
                    app.UNK(1).fileinfo = dir(app.A.unkfullfilename);
                    app.UNK(1).num_elements = size(app.UNK(1).colheaders(2:end));
                    app.UNK(1).num_elements = app.UNK(1).num_elements(2);
                    app.UNK(1).bgwindow = [];
                    app.UNK(1).mat1window = [];
                    app.UNK(1).mat2window = [];
                    app.UNK(1).mattotal = [];
                    app.UNK(1).comp1window = [];
                    app.UNK(1).comp2window = [];
                    app.UNK(1).comp3window = [];
                    app.UNK(1).sigtotal = [];
                    app.UNK(1).MAT_corrtype = 1;
                    app.UNK(1).MATcorrfile = 1;
                    app.UNK(1).MATQIS = 1;
                    app.UNK(1).MATQISiso = 1;
                    app.UNK(1).MATQISox = 1;

                    app.UNK(1).Info = [];

                    app.UNK(1).SIG_constraint1 = 1;
                    app.UNK(1).SIG_constraint2 = 1;

                    app.UNK(1).SIGQIS1 = 1;
                    app.UNK(1).SIGQIS1iso = 1;
                    app.UNK(1).SIGQIS1ox = 1;
                    app.UNK(1).SIG1unit = 1;
                    app.UNK(1).SIGQIS2 = 1;
                    app.UNK(1).SIGQIS2iso = 1;
                    app.UNK(1).SIGQIS2ox = 1;
                    app.UNK(1).SIG2unit = 1;
                    app.UNK(1).timepoint = 1;

                    app.UNK(1).SIG_tracer = 1;
                    app.UNK(1).fileinfo.ext = ext1;
                    if strcmp(ext1,'.FIN2')
                        app.UNK(1).fileinfo.date = dateFIN;
                    end

                    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                    %clear these in case former standards or unknowns were loaded that had
                    %a different isotope list
                    app.A.ISOTOPE_list = {};
                    app.A.DT_VALUES = [];
                    app.A.ELEMENT_list = {};

                    app.A.ISOTOPE_list = app.UNK(1).colheaders(2:end);
                    app.A.ISOTOPE_num = size(app.A.ISOTOPE_list);
                    app.A.ISOTOPE_num = app.A.ISOTOPE_num(2);
                    app.A.DT_VALUES = 0.01 * ones(app.A.ISOTOPE_num,1);

                    %create a list of elements analysed

                    isotope = char(app.A.ISOTOPE_list); %convert isotopes into a character array
                    iselement = isletter(isotope);  %search for letters within the 'isotope' array
                    element = iselement.*isotope;
                    element2 = char(element);
                    for c = 1:app.A.ISOTOPE_num
                        app.A.ELEMENT_list(c) = {element2(c,:)};
                    end
                    app.A.ELEMENT_list = deblank(app.A.ELEMENT_list);
                    app.A.ELEMENT_num = size(app.A.ELEMENT_list);
                    app.A.ELEMENT_num = app.A.ELEMENT_num(2);
                    clear isotope iselement element element2
                    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

                    app.UNK(1).SALT = zeros(app.A.ELEMENT_num,1); %whether isotopes are included in a salt correction or not
                    app.UNK(1).SALT_mass_balance_factor = 0.5; %Added in 1.0.6


                    app.A.k = -1;   %counter for the number of unknowns opened (irrespective of number deleted)
                    %This counter goes in the negative direction, in order to distinguish it from the standards
                    app.A.K = 0;    %number of open app.UNK windows

                    app.UNK(1).order_opened = app.A.k;
                    app.UNK(1).figure_state = 'shut';
                    app.UNK(1).spikestatus = 'undone';

                    app.A.UNK_active = 1; %total number of unknowns
                    app.A.UNKPOPUPLIST(1,1) = {app.UNK(1).fileinfo.name};

                    %SILLSFIG_UPDATE
                end
            end

            app.A.KC = app.A.UNK_active; %by default the current unknown is the last one loaded

            app.A.UNKfigs_open = {app.UNK.figure_state};
            app.A.UNKfigs_open = strcmp(app.A.UNKfigs_open,'open');

            UNKRESETX = ['if strcmp(get(gca,''tag''),''app.UNK.handles.h_unkplot'');'...
                'set(gca,''XLim'',app.UNK(app.A.KC).XLim_orig,''YLim'',app.UNK(app.A.KC).YLim_orig);end;'];

            clear unkfilenum i UNKIMP UNKIMP_num ext1

            app.h_currentUNK_popup.Items=app.A.UNKPOPUPLIST;
            app.h_currentUNK_popup.ItemsData=1:numel(app.A.UNKPOPUPLIST);

            figure(app.h_fig);
        end


        function reset_zoom_pan(app)
            try
                zoom(app.STD_Plt,'off')
                pan(app.STD_Plt,'off')
            catch ME
                disp('Error - reset zoom / pan STD_Plt')
                disp(ME)
            end

            try
                zoom(app.UNK_Plt,'off')
                pan(app.UNK_Plt,'off')
            catch ME
                disp('Error - reset zoom / pan STD_Plt')
                disp(ME)
            end
        end


        function update_areas(app,which)

            if strcmp(which,'all')
                tmp=findobj(app.UNK_Plt,'Type','Patch');
                delete(tmp);
                tmp=findobj(app.STD_Plt,'Type','Patch');
                delete(tmp);

                n_k=1:2:numel(app.hVerticalLines);
            elseif strcmp(which,'STD')
                tmp=findobj(app.STD_Plt,'Type','Patch');
                delete(tmp);

                n_k=[1,3];
            elseif strcmp(which,'UNK')
                tmp=findobj(app.UNK_Plt,'Type','Patch');
                delete(tmp);
                n_k=5:2:numel(app.hVerticalLines);
            end

            for k=n_k
                try
                if numel(app.hVerticalLines)>k &&  not(isempty(app.hVerticalLines(k).xl)) && not(isempty(app.hVerticalLines(k+1).xl)) && isvalid(app.hVerticalLines(k).xl) && isvalid(app.hVerticalLines(k+1).xl)
                    if k<5 
                        ax2plot=app.STD_Plt;
                    else
                        ax2plot=app.UNK_Plt;
                    end
                    ylimits = ylim(ax2plot);
                    x=sort([app.hVerticalLines(k).xl.Value app.hVerticalLines(k+1).xl.Value]);
                    lowerBoundary(1:2)=min([ylimits ylimits]);
                    upperBoundary(1:2)=max([ylimits ylimits]);
                    patch(ax2plot,[x fliplr(x)], [upperBoundary  fliplr(lowerBoundary)], app.hVerticalLines(k+1).xl.Color,'FaceAlpha',0.2,'hittest','off');
                    ylim(ax2plot,ylimits);
                end
                catch ME
                    ME;
                end
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)


            %get the current sills directory
            app.A.sillspath = pwd; %ATH update for

            % initializes the Structs
            [app.SMAN,app.STD,app.UNK, app.A, app.SRM] = get_sills_data;


            %loads the SRMs
            SRM_load_startup(app)
        end

        % Button pushed function: h_load_std_button
        function h_load_std_buttonPushed(app, event)
            [app.A.stdfile,app.A.sillspath] = uigetfile([app.A.sillspath '/*.*'],'Browse for standard file','MultiSelect','on');
            if isnumeric(app.A.stdfile) && app.A.stdfile == 0
                return
            elseif not(iscellstr(app.A.stdfile)) %i.e. Multiple files, Added in 1.0.6
                app.A.stdfile={app.A.stdfile};

            end

            stdfile_multiload(app)

            %SILLSFIG_UPDATE
            SillsGuiUpdate(app)
        end

        % Button pushed function: h_std_plot_button
        function h_std_plot_buttonButtonPushed(app, event)
            %figure
           % ax=nexttile;
            %update_std_plot_fun(app,ax,false)


            standard_plot_app(app)


        end

        % Value changed function: h_currentSTD_popup
        function h_currentSTD_popupValueChanged(app, event)
            try
                % update active entry
                app.A.STD_active = app.h_currentSTD_popup.Value;

                % update SRM
                if ismember(app.STD(app.A.STD_active).SRM , app.h_currentSTD_SRM_out.Items)
                    app.h_currentSTD_SRM_out.Value = app.STD(app.A.STD_active).SRM;
                else
                    app.h_currentSTD_SRM_out.Value = 'Assign SRM';
                end

                %update the preview plot
                update_std_plot_fun(app,app.STD_Plt,true)

                % update HH:mm
                app.h_currentSTD_drifthh_edit.Value = str2double(app.STD(app.A.STD_active).hh);
                app.h_currentSTD_driftmm_edit.Value = str2double(app.STD(app.A.STD_active).mm);

            catch ME
                DisplayErrorNG(app,ME,'h_currentSTD_popupValueChanged')
            end
        end

        % Button pushed function: CopyStandardButton
        function CopyStandardButtonPushed(app, event)
            app.h_currentSTD_popup.Value

            app.STD(app.A.STD_active + 1)

            app.STD=[app.STD1(1:app.A.STD_active) app.STD1(app.A.STD_active:end) ];

            SillsGuiUpdate(app)
        end

        % Button pushed function: h_std_delete_button
        function h_std_delete_buttonButtonPushed(app, event)
            app.h_currentSTD_popup.Value

            %app.STD(app.A.STD_active + 1)

            app.STD(app.A.STD_active)=[];
            app.A.STDPOPUPLIST(app.A.STD_active)=[];

            if app.A.STD_active > 1
                app.A.STD_active=app.A.STD_active-1;
            elseif isempty(app.A.STDPOPUPLIST)
                app.A.STD_active = [];
                cla(app.STD_Plt)

            end
            %ath check if last entry

            SillsGuiUpdate(app)
        end

        % Menu selected function: SRMdefine
        function SRMdefineMenuSelected(app, event)

        end

        % Menu selected function: SRMLoad
        function SRMLoadMenuSelected(app, event)

            % use uigetfile command to browse for the SRM file
            [app.A.userSRMfile,app.A.userSRMpathname] = uigetfile('*.xls*','Browse for SRM composition file');
            app.A.SRMcheck = strcmp(app.A.SRMPOPUPLIST,app.A.userSRMfile);

            if ismember(app.A.userSRMfile,app.A.SRMPOPUPLIST)
                %warndlg('This SRM is already in the list.','SILLS Warning');
                error_message='This SRM is already in the list.';
                uialert(app.h_fig,error_message,'SILLS Warning','warining');
                return
            else
                % create a name for the file from its file path
                app.A.USERSRMFILE = [app.A.userSRMpathname app.A.userSRMfile];
                if app.A.USERSRMFILE == 0 % i.e. if the window was cancelled
                    figure(app.h_fig); % make SILLS Control Panel the current figure
                    return % and quit the USERSRM_LOAD.m routine
                else


                    SRM_file=fullfile(pwd,['/SRM Files/' app.A.userSRMfile]);
                    load_SRM_NG(app,SRM_file,true)

                    % app.A.SRM_num = app.A.SRM_num + 1; % a new SRM added

                    %                     B = importdata([pwd '/SRM Files/' app.A.userSRMfile]); %Changed in 1.0.4
                    %                     app.SRM(app.A.SRM_num,1).data = B.data;
                    %                     app.SRM(app.A.SRM_num,1).textdata = B.textdata;
                    %                     app.SRM(app.A.SRM_num,1).rowheaders = B.rowheaders;
                    %                     app.SRM(app.A.SRM_num,1).fileinfo = dir(fullfile(pwd,['/SRM Files/' app.A.userSRMfile])); %Changed in 1.0.4
                    %                     app.SRM(app.A.SRM_num,1).name = app.SRM(app.A.SRM_num,1).fileinfo.name;

                    %now update the popuplist of SRM items


                end
            end


        end

        % Value changed function: h_currentSTD_SRM_out
        function h_currentSTD_SRM_outValueChanged(app, event)
            if not(app.A.STD_active==0)
                app.STD(app.A.STD_active).SRM=app.h_currentSTD_SRM_out.Value;
            end
        end

        % Button pushed function: h_load_unk_button
        function h_load_unk_buttonButtonPushed(app, event)
            try
                [app.A.unkfile,app.A.sillspath] = uigetfile([app.A.sillspath '/*.*'],'Browse for standard file','MultiSelect','on'); %Changed in 1.0.6
                if isnumeric(app.A.unkfile) && app.A.unkfile == 0
                    return
                elseif not(iscellstr(app.A.unkfile)) %i.e. Multiple files, Added in 1.0.6
                    app.A.unkfile={app.A.unkfile};

                end
                unkfile_multiload(app)
                %SILLSFIG_UPDATE
                SillsGuiUpdate(app)
            catch ME
                DisplayErrorNG(app,ME,'h_load_unk_buttonButtonPushed')
            end
        end

        % Button pushed function: h_unk_plot_button
        function h_unk_plot_buttonButtonPushed(app, event)
   figure
            ax=nexttile;
            update_unk_plot_fun(app,ax,true)
            hold on


        end

        % Value changed function: h_currentUNK_popup
        function h_currentUNK_popupValueChanged(app, event)
            app.A.UNK_active = app.h_currentUNK_popup.Value;

            update_unk_plot_fun(app,app.UNK_Plt,true)


        end

        % Button pushed function: h_unk_delete_button
        function h_unk_delete_buttonButtonPushed(app, event)

        end

        % Button pushed function: h_unk_copy_button
        function h_unk_copy_buttonButtonPushed(app, event)

        end

        % Value changed function: h_currentSTD_drifthh_edit, 
        % ...and 1 other component
        function h_currentSTD_drifthh_editValueChanged(app, event)
            value = app.h_currentSTD_drifthh_edit.Value;

        end

        % Callback function
        function ButtonPushed(app, event)

        end

        % Button pushed function: LASAButton
        function LASAButtonPushed(app, event)
            app.LASA=Import_Module(app)
        end

        % Menu selected function: wksopen
        function wksopenMenuSelected(app, event)
            [file,path]= uigetfile;
            load(fullfile(path, file));
            app.A=A;
            app.SRM=SRM;
            app.UNK=UNK;
            app.STD=STD;
            app.SMAN=SMAN;
            app.REPORT=REPORT;
            %quick and dirty solution
            %only used for import of old SILLS files
            %früher waren die Standards Nummern, jetzt sind die Strings
            for n=1:numel({app.STD(:).SRM})
                app.STD(n).SRM = app.A.SRMPOPUPLIST(app.STD(n).SRM);
            end

            % we use the A.UNK_active as the variable that is selected in
            % the dropdown
            %The A.UNK_num is the original value
            app.A.UNK_active = app.A.UNK_num;
            app.A.STD_active = app.A.STD_num;
            % the GUI must be updated so that whenever the dropdown is
            % changed, the A.UNK_num and STD_num stay true to the original
            % value and dont get changed because of the dropdown
            SillsGuiUpdate(app)
        end

        % Menu selected function: GenRepTestMenu
        function GenRepTestMenuSelected(app, event)

            try
                [app.A, app.UNK,app.STD, app.SRM, app.SMAN, app.REPORT] = REPORT_WRITE_X(app.A, app.UNK,app.STD, app.SRM, app.SMAN, app.REPORT);

                message = "This worked :)";
                uialert(app.h_fig,message,"success", ...
                    "Icon","success");

            catch ME
                DisplayErrorNG(app,ME,'CALC_EVALUATE')

                DisplayErrorNG(app,ME,'REPORT_WRITE')
                message = "HAHA Fail :D";
                uialert(app.h_fig,message,"Warning", ...
                    "Icon","warning");
            end

            %Report_config after execution of report_write, open report
            %config window (where you can choose Sample, LOD and so on) and
            %include the function Report_config
            % this Report_config has to be the GUI
        end

        % Menu selected function: Calc_EvaluateTestMenu
        function Calc_EvaluateTestMenuSelected(app, event)
            SillsGuiUpdate(app)
            try
                [app.A, app.UNK,app.STD, app.SRM, app.SMAN] = CALC_EVALUATE_X(app.A, app.UNK,app.STD, app.SRM, app.SMAN);

                message = "This worked :)";
                uialert(app.h_fig,message,"success", ...
                    "Icon","success");

            catch ME
                DisplayErrorNG(app,ME,'CALC_EVALUATE')

                DisplayErrorNG(app,ME,'DATAFILTER')
                message = "HAHA Fail :D";
                uialert(app.h_fig,message,"Warning", ...
                    "Icon","warning");
            end
        end

        % Menu selected function: DatafilterTestMenu
        function DatafilterTestMenuSelected(app, event)
            try
                [app.A, app.UNK,app.STD, app.SRM] = DATAFILTER_X(app.A, app.UNK,app.STD, app.SRM);

                message = "This worked :)";
                uialert(app.h_fig,message,"success", ...
                    "Icon","success");

            catch ME
                DisplayErrorNG(app,ME,'DATAFILTER')
                message = "HAHA Fail :D";
                uialert(app.h_fig,message,"Warning", ...
                    "Icon","warning");
            end
        end

        % Menu selected function: DatadriftTestMenu
        function DatadriftTestMenuSelected(app, event)

            try
                [app.A, app.UNK,app.STD, app.SRM] = DATADRIFT_X(app.A, app.UNK,app.STD, app.SRM);

                message = "This worked :)";
                uialert(app.h_fig,message,"success", ...
                    "Icon","success");

            catch ME
                DisplayErrorNG(app,ME,'DATADRIFT')

                message = "HAHA Fail :D";
                uialert(app.h_fig,message,"Warning", ...
                    "Icon","warning");
            end


        end

        % Button pushed function: h_CALC_manager
        function h_CALC_managerButtonPushed(app, event)
            calculation_manager(app) %calling the calculation_manager with Mainapp as input
        end

        % Button pushed function: ImportModuleButton
        function ImportModuleButtonPushed(app, event)
            Import_Module(app)
        end

        % Button down function: STD_Plt
        function STD_PltButtonDown(app, event)
            if isempty(app.hLineToDrag) || numel(app.hLineToDrag)==0
                reset_zoom_pan(app)

                for k=1:4
                    if numel(app.hVerticalLines)<k || isempty(app.hVerticalLines(k).xl) || not(isvalid(app.hVerticalLines(k).xl))
                        continue
                    end
                    xVertLineCoord = get(app.hVerticalLines(k).xl,'Value');
                    click_buffer=(max(app.STD_Plt.XLim)-min(app.STD_Plt.XLim))*0.01; %ATH Settingspanel
                    xVertLineCoord(1)=xVertLineCoord(1);
                    if abs(event.IntersectionPoint(1) - xVertLineCoord(1)) < click_buffer
                        app.hLineToDrag = app.hVerticalLines(k);
                        %  disp(app.hLineToDrag(1).xl.Tag)
                        return;
                    end
                end

                if numel(app.hVerticalLines)>1 && not(isempty(app.hVerticalLines(1).xl))&&  isvalid(app.hVerticalLines(1).xl) && not(isempty(app.hVerticalLines(2).xl))&&  isvalid(app.hVerticalLines(2).xl)&& get(app.hVerticalLines(1).xl,'Value')<abs(event.IntersectionPoint(1)) && get(app.hVerticalLines(2).xl,'Value')>abs(event.IntersectionPoint(1))
                    app.hLineToDrag = app.hVerticalLines(1:2);
                    return;
                elseif numel(app.hVerticalLines)>3 && not(isempty(app.hVerticalLines(3).xl))&& isvalid(app.hVerticalLines(3).xl) && not(isempty(app.hVerticalLines(4).xl))&&  isvalid(app.hVerticalLines(4).xl)&& get(app.hVerticalLines(3).xl,'Value')<abs(event.IntersectionPoint(1)) && get(app.hVerticalLines(4).xl,'Value')>abs(event.IntersectionPoint(1))
                    app.hLineToDrag = app.hVerticalLines(3:4);
                    return;
                end
            end
        end

        % Button down function: UNK_Plt
        function UNK_PltButtonDown(app, event)
            if isempty(app.hLineToDrag) || numel(app.hLineToDrag)==0
                reset_zoom_pan(app)

                for k=5:16
                    if numel(app.hVerticalLines)<k || isempty(app.hVerticalLines(k).xl) || not(isvalid(app.hVerticalLines(k).xl))
                        continue
                    end
                    try
                        xVertLineCoord = get(app.hVerticalLines(k).xl,'Value');
                        click_buffer=(max(app.UNK_Plt.XLim)-min(app.UNK_Plt.XLim))*0.01; %ATH Settingspanel
                        xVertLineCoord(1)=xVertLineCoord(1);
                        if abs(event.IntersectionPoint(1) - xVertLineCoord(1)) < click_buffer
                            app.hLineToDrag = app.hVerticalLines(k);
                            %        disp(app.hLineToDrag(1).xl.Tag)

                            return;
                        end
                    catch
                        0;
                    end
                end

                click_buffer=(max(app.UNK_Plt.XLim)-min(app.UNK_Plt.XLim))*0.2;
                try
                    if numel(app.hVerticalLines)>5 && not(isempty(app.hVerticalLines(5).xl))&& isvalid(app.hVerticalLines(5).xl)&& not(isempty(app.hVerticalLines(6).xl))&& isvalid(app.hVerticalLines(6).xl)&&get(app.hVerticalLines(5).xl,'Value')<abs(event.IntersectionPoint(1)) && get(app.hVerticalLines(6).xl,'Value')>abs(event.IntersectionPoint(1))
                        app.hLineToDrag = app.hVerticalLines(5:6);
                    elseif numel(app.hVerticalLines)>7 && not(isempty(app.hVerticalLines(7).xl))&& isvalid(app.hVerticalLines(7).xl)&& not(isempty(app.hVerticalLines(8).xl))&& isvalid(app.hVerticalLines(8).xl)&&get(app.hVerticalLines(7).xl,'Value')<abs(event.IntersectionPoint(1)) && get(app.hVerticalLines(8).xl,'Value')>abs(event.IntersectionPoint(1))
                        app.hLineToDrag = app.hVerticalLines(7:8);
                    elseif numel(app.hVerticalLines)>9 && not(isempty(app.hVerticalLines(9).xl))&& isvalid(app.hVerticalLines(9).xl)&& not(isempty(app.hVerticalLines(10).xl))&& isvalid(app.hVerticalLines(10).xl)&& get(app.hVerticalLines(9).xl,'Value')<abs(event.IntersectionPoint(1)) && get(app.hVerticalLines(10).xl,'Value')>abs(event.IntersectionPoint(1))
                        app.hLineToDrag = app.hVerticalLines(9:10);
                    elseif numel(app.hVerticalLines)>11 && not(isempty(app.hVerticalLines(11).xl))&& isvalid(app.hVerticalLines(11).xl)&& not(isempty(app.hVerticalLines(12).xl))&& isvalid(app.hVerticalLines(12).xl)&&get(app.hVerticalLines(11).xl,'Value')<abs(event.IntersectionPoint(1)) && get(app.hVerticalLines(12).xl,'Value')>abs(event.IntersectionPoint(1))
                        app.hLineToDrag = app.hVerticalLines(11:12);
                    elseif numel(app.hVerticalLines)>13 && not(isempty(app.hVerticalLines(13).xl))&& isvalid(app.hVerticalLines(13).xl)&& not(isempty(app.hVerticalLines(14).xl))&& isvalid(app.hVerticalLines(14).xl)&&get(app.hVerticalLines(13).xl,'Value')<abs(event.IntersectionPoint(1)) && get(app.hVerticalLines(14).xl,'Value')>abs(event.IntersectionPoint(1))
                        app.hLineToDrag = app.hVerticalLines(13:14);
                    elseif numel(app.hVerticalLines)>15 && not(isempty(app.hVerticalLines(15).xl))&& isvalid(app.hVerticalLines(15).xl)&& not(isempty(app.hVerticalLines(16).xl))&& isvalid(app.hVerticalLines(16).xl)&& get(app.hVerticalLines(15).xl,'Value')<abs(event.IntersectionPoint(1)) && get(app.hVerticalLines(16).xl,'Value')>abs(event.IntersectionPoint(1))
                        app.hLineToDrag = app.hVerticalLines(15:16);
                    end
                    if not(isempty(app.hLineToDrag))
                        %                     disp(app.hLineToDrag(1).xl.Tag)
                        %                     disp(app.hLineToDrag(2).xl.Tag)
                    end
                catch ME
                    00;
                end
            end

        end

        % Window button motion function: h_fig
        function h_figWindowButtonMotion(app, event)


            if ~isempty(app.hLineToDrag) && numel(app.hLineToDrag)>0
                if startsWith(app.hLineToDrag(1).xl.Tag,'STD')
                    currentPoint   = app.STD_Plt.CurrentPoint;
                    x            = currentPoint(1,1);
                    y            = currentPoint(1,2);
                    xlim_ = app.STD_Plt.XLim;
                    ylim_ = app.STD_Plt.YLim;
                    inside_STD_Plt = x >= xlim_(1) & x <= xlim_(2) & y >= ylim_(1) & y <= ylim_(2); % x >= xlim(1) & x <= xlim(2) & y >= ylim(1) & y <= ylim(2)
                    inside_UNK_Plt=false;
                else

                    currentPoint   = app.UNK_Plt.CurrentPoint;
                    x            = currentPoint(1,1);
                    y            = currentPoint(1,2);
                    xlim_ = app.UNK_Plt.XLim;
                    ylim_ = app.UNK_Plt.YLim;
                    inside_UNK_Plt = x >= xlim_(1) & x <= xlim_(2) & y >= ylim_(1) & y <= ylim_(2); % x >= xlim(1) & x <= xlim(2) & y >= ylim(1) & y <= ylim(2)
                    inside_STD_Plt =false;
                end


                if (inside_STD_Plt || inside_UNK_Plt)

                    if inside_STD_Plt
                        currentPoint   = app.STD_Plt.CurrentPoint;
                        x            = currentPoint(1,1);
                        % disp('std')
                        %% check the position of the other lines
                        if isempty(app.hVerticalLinesBounds)
                            tmp=nan(1,4);
                            for n=1:4
                                if numel(app.hVerticalLines)>=n &&isvalid(app.hVerticalLines(n).xl) && not(isequal(app.hVerticalLines(n).xl,app.hLineToDrag(1).xl))
                                    tmp(n)=[app.hVerticalLines(n).xl.Value];
                                end
                            end
                        end
                    elseif inside_UNK_Plt
                        currentPoint   = app.UNK_Plt.CurrentPoint;
                        x            = currentPoint(1,1);
                        %  disp('unk')
                        if isempty(app.hVerticalLinesBounds)
                            tmp=nan(1,4);
                            for n=5:16
                                if numel(app.hVerticalLines)>=n && not(isempty(app.hVerticalLines(n).xl)) && isvalid(app.hVerticalLines(n).xl) && not(isequal(app.hVerticalLines(n).xl,app.hLineToDrag(1).xl))
                                    tmp(n-4)=[app.hVerticalLines(n).xl.Value];
                                end
                            end
                        end
                    end

                    if isempty(app.hVerticalLinesBounds)
                        tmp=tmp(not(ismember(tmp,get(app.hLineToDrag(1).xl,'Value'))));
                        if numel(app.hLineToDrag)==2
                            tmp=tmp(not(ismember(tmp,get(app.hLineToDrag(2).xl,'Value'))));
                        end

                        tmp_larger=[tmp(tmp>x) max(xlim_)];
                        tmp_smaller=[tmp(tmp<x) min(xlim_)];
                        if numel(app.hLineToDrag)==2
                            tmp_larger=tmp_larger-abs(app.hLineToDrag(2).xl.Value-app.hLineToDrag(1).xl.Value)./2;
                            tmp_smaller=tmp_smaller+abs(app.hLineToDrag(2).xl.Value-app.hLineToDrag(1).xl.Value)./2;
                        end

                        [~, ix_larger]=min(abs(x-tmp_larger));
                        [~, ix_smaller]=min(abs(x-tmp_smaller));

                        upperbound=tmp_larger(ix_larger);
                        lowerbound=tmp_smaller(ix_smaller);
                        app.hVerticalLinesBounds=[lowerbound,upperbound];
                    else
                        lowerbound=app.hVerticalLinesBounds(1);
                        upperbound=app.hVerticalLinesBounds(2);
                    end
                    inside_bounds = x > lowerbound & x < upperbound;

                    %numel(app.hLineToDrag)
                    if   inside_bounds
                        if numel(app.hLineToDrag)==2

                            xposlines=[get(app.hLineToDrag(1).xl,'Value') get(app.hLineToDrag(2).xl,'Value')];
                            difference=range(xposlines)/2; % here get the difference between the lines

                            x1=x-difference;
                            x2=x+difference;

                            set(app.hLineToDrag(1).xl, 'Value', x1);
                            set(app.hLineToDrag(2).xl, 'Value', x2);

                            %  xposlines=[get(app.hVerticalLines(1),'Value') get(app.hVerticalLines(2),'Value')];
                            %                         app.EditField_xlimMin.Value = min(xposlines);
                            %                         app.EditField_xlimMax.Value = max(xposlines);

                        else
                            set(app.hLineToDrag(1).xl, 'Value', x);
                            %disp(app.hLineToDrag(1).xl.Tag)
                            %  xposlines=[get(app.hVerticalLines(1),'Value') get(app.hVerticalLines(2),'Value')];
                            %                         app.EditField_xlimMin.Value = min(xposlines);
                            %                         app.EditField_xlimMax.Value = max(xposlines);
                        end
                        if inside_UNK_Plt
                            update_areas(app, 'UNK'); % Update patches for UNK plot
                        else
                            update_areas(app, 'STD');  % Update patches for STD plot
                        end
                    end
                else
                    h_figWindowButtonUp(app)
                end


            end
        end

        % Window button up function: h_fig
        function h_figWindowButtonUp(app, event)
            %% here the interval values are refreshed when the intervals were moved
            
            if ~isempty(app.hLineToDrag)
                    if not(isempty(app.hLineToDrag)) && isvalid(app.hLineToDrag(1).xl)

                        %% Standard Background window
                        if strcmp(app.hLineToDrag(1).xl.Tag,'STD_bgwindow')
                            %   disp('Update_Std_BG')

                            % get the position of the vertical lines, these
                            % positions can be anywhere on the x-axis, this is why we
                            % need to find the closest index first and then
                            % get the real time value
                            xpos = sort([app.hVerticalLines(1).xl.Value,app.hVerticalLines(2).xl.Value]);
                            % get the index of the closest time (x-axis)
                            [~,app.STD(app.A.STD_active).bgwindow_index(1)] = min(abs(app.STD(app.A.STD_active).data(:,1)-xpos(1)));
                            [~,app.STD(app.A.STD_active).bgwindow_index(2)] = min(abs(app.STD(app.A.STD_active).data(:,1)-xpos(2)));
                            %overwrite the STD.bgwindow values
                            app.STD(app.A.STD_active).bgwindow(1) = app.STD(app.A.STD_active).data(app.STD(app.A.STD_active).bgwindow_index(1),1);
                            app.STD(app.A.STD_active).bgwindow(2) = app.STD(app.A.STD_active).data(app.STD(app.A.STD_active).bgwindow_index(2),2);
                            
                            %% here I would also need a function that updates all the interval stuff accordingly (bg_cps and so on)


                        %% Standard Signal window
                        elseif strcmp(app.hLineToDrag(1).xl.Tag,'STD_sigwindow')
                            %     disp('Update_Std_signalwindow')
                            xpos = sort([app.hVerticalLines(3).xl.Value,app.hVerticalLines(4).xl.Value]); % position of line
                            % get the index of the closest time (x-axis)
                            [~,app.STD(app.A.STD_active).sigwindow_index(1)] = min(abs(app.STD(app.A.STD_active).data(:,1)-xpos(1)));
                            [~,app.STD(app.A.STD_active).sigwindow_index(2)] = min(abs(app.STD(app.A.STD_active).data(:,1)-xpos(2)));
                            %overwrite the STD.sigwindow values
                            app.STD(app.A.STD_active).sigwindow(1) = app.STD(app.A.STD_active).data(app.STD(app.A.STD_active).sigwindow_index(1),1);
                            app.STD(app.A.STD_active).sigwindow(2) = app.STD(app.A.STD_active).data(app.STD(app.A.STD_active).sigwindow_index(2),2);

                        elseif strcmp(app.hLineToDrag(1).xl.Tag,'UNK_bgwindow')
                            %% continue with the rest in the same way
                            sort([app.hVerticalLines(5).xl.Value,app.hVerticalLines(6).xl.Value]); % position of line
                        elseif strcmp(app.hLineToDrag(1).xl.Tag,'UNK_mat1window')
                            sort([app.hVerticalLines(7).xl.Value,app.hVerticalLines(8).xl.Value]); % position of line
                        elseif strcmp(app.hLineToDrag(1).xl.Tag,'UNK_mat2window')
                            sort([app.hVerticalLines(9).xl.Value,app.hVerticalLines(10).xl.Value]); % position of line
                        elseif strcmp(app.hLineToDrag(1).xl.Tag,'UNK_comp1window')
                            sort([app.hVerticalLines(11).xl.Value,app.hVerticalLines(12).xl.Value]); % position of line
                        elseif strcmp(app.hLineToDrag(1).xl.Tag,'UNK_comp2window')
                            sort([app.hVerticalLines(13).xl.Value,app.hVerticalLines(14).xl.Value]); % position of line
                        elseif strcmp(app.hLineToDrag(1).xl.Tag,'UNK_comp3window')
                            sort([app.hVerticalLines(15).xl.Value,app.hVerticalLines(16).xl.Value]); % position of line

                        end
                    end
                    app.hVerticalLinesBounds=[];
                    app.hLineToDrag = [];
                    % update_areas(xx)
                    update_areas(app,'all')
                    app.hVerticalLinesBounds=[];
                    app.hLineToDrag = [];
                    update_areas(app,'all')

            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.h_fig = uifigure('Visible', 'off');
            app.h_fig.Name = 'SILLS X';
            app.h_fig.WindowButtonUpFcn = createCallbackFcn(app, @h_figWindowButtonUp, true);
            app.h_fig.WindowButtonMotionFcn = createCallbackFcn(app, @h_figWindowButtonMotion, true);
            app.file = uimenu(app.h_fig);
            app.file.Text = 'File';
            app.wkspnew = uimenu(app.file);
            app.wkspnew.Text = 'New Project';
            app.wksopen = uimenu(app.file);
            app.wksopen.MenuSelectedFcn = createCallbackFcn(app, @wksopenMenuSelected, true);
            app.wksopen.Text = 'Open Project';
            app.wkspsave = uimenu(app.file);
            app.wkspsave.Text = 'Save Project';
            app.ExitMenu = uimenu(app.file);
            app.ExitMenu.Text = 'Exit';
            app.settings = uimenu(app.h_fig);
            app.settings.Text = 'Settings';
            app.input = uimenu(app.settings);
            app.input.Text = 'Input Format';
            app.cps = uimenu(app.input);
            app.cps.Text = 'Counts per Second';
            app.counts = uimenu(app.input);
            app.counts.Text = 'Absolute Counts';
            app.time_format = uimenu(app.settings);
            app.time_format.Text = 'Time Format';
            app.drifthhmm = uimenu(app.time_format);
            app.drifthhmm.Text = 'HH:MM';
            app.driftpoints = uimenu(app.time_format);
            app.driftpoints.Text = 'Integer Time Points';
            app.SRMMenu = uimenu(app.settings);
            app.SRMMenu.Text = 'Standard Reference Materials';
            app.SRMLoad = uimenu(app.SRMMenu);
            app.SRMLoad.MenuSelectedFcn = createCallbackFcn(app, @SRMLoadMenuSelected, true);
            app.SRMLoad.Text = 'Load SRM from File';
            app.SRMdefine = uimenu(app.SRMMenu);
            app.SRMdefine.MenuSelectedFcn = createCallbackFcn(app, @SRMdefineMenuSelected, true);
            app.SRMdefine.Text = 'Define new SRM';
            app.dwelltimes = uimenu(app.settings);
            app.dwelltimes.Text = 'Set Dwell Times';
            app.flicker = uimenu(app.settings);
            app.flicker.Text = 'Set Flicker Noise';
            app.automate = uimenu(app.h_fig);
            app.automate.Text = 'Automate';
            app.autotime = uimenu(app.automate);
            app.autotime.Text = 'Retrieve Times from File Info';
            app.autoint = uimenu(app.automate);
            app.autoint.Text = 'Set Integration Intervals Automatically';
            app.help = uimenu(app.h_fig);
            app.help.Text = 'Help';
            app.manual = uimenu(app.help);
            app.manual.Text = 'SILLS Manual';
            app.about = uimenu(app.help);
            app.about.Text = 'About SILLS';
            app.reparation = uimenu(app.help);
            app.reparation.Text = 'Reparation';
            app.DevMenu = uimenu(app.h_fig);
            app.DevMenu.Text = 'Dev';
            app.DatadriftTestMenu = uimenu(app.DevMenu);
            app.DatadriftTestMenu.MenuSelectedFcn = createCallbackFcn(app, @DatadriftTestMenuSelected, true);
            app.DatadriftTestMenu.Text = 'DatadriftTest';
            app.DatafilterTestMenu = uimenu(app.DevMenu);
            app.DatafilterTestMenu.MenuSelectedFcn = createCallbackFcn(app, @DatafilterTestMenuSelected, true);
            app.DatafilterTestMenu.Text = 'DatafilterTest';
            app.Calc_EvaluateTestMenu = uimenu(app.DevMenu);
            app.Calc_EvaluateTestMenu.MenuSelectedFcn = createCallbackFcn(app, @Calc_EvaluateTestMenuSelected, true);
            app.Calc_EvaluateTestMenu.Text = 'Calc_EvaluateTest';
            app.GenRepTestMenu = uimenu(app.DevMenu);
            app.GenRepTestMenu.MenuSelectedFcn = createCallbackFcn(app, @GenRepTestMenuSelected, true);
            app.GenRepTestMenu.Text = 'GenRepTest';
            app.GridLayout = uigridlayout(app.h_fig);
            app.UNK_Plt = uiaxes(app.GridLayout);
            title(app.UNK_Plt, 'Unknown')
            xlabel(app.UNK_Plt, 'X')
            ylabel(app.UNK_Plt, 'Y')
            zlabel(app.UNK_Plt, 'Z')
            app.UNK_Plt.ButtonDownFcn = createCallbackFcn(app, @UNK_PltButtonDown, true);
            app.STD_Plt = uiaxes(app.GridLayout);
            title(app.STD_Plt, 'Standard')
            xlabel(app.STD_Plt, 'X')
            ylabel(app.STD_Plt, 'Y')
            zlabel(app.STD_Plt, 'Z')
            app.STD_Plt.ButtonDownFcn = createCallbackFcn(app, @STD_PltButtonDown, true);
            app.h_load_std_button = uibutton(app.GridLayout, 'push');
            app.h_load_std_button.ButtonPushedFcn = createCallbackFcn(app, @h_load_std_buttonPushed, true);
            app.h_load_std_button.Text = 'Load Standard';
            app.h_load_unk_button = uibutton(app.GridLayout, 'push');
            app.h_load_unk_button.ButtonPushedFcn = createCallbackFcn(app, @h_load_unk_buttonButtonPushed, true);
            app.h_load_unk_button.Text = 'Load Unknown';
            app.h_std_plot_button = uibutton(app.GridLayout, 'push');
            app.h_std_plot_button.ButtonPushedFcn = createCallbackFcn(app, @h_std_plot_buttonButtonPushed, true);
            app.h_std_plot_button.Text = 'Plot Standard';
            app.h_unk_plot_button = uibutton(app.GridLayout, 'push');
            app.h_unk_plot_button.ButtonPushedFcn = createCallbackFcn(app, @h_unk_plot_buttonButtonPushed, true);
            app.h_unk_plot_button.Text = 'Plot Unknown';
            app.h_std_delete_button = uibutton(app.GridLayout, 'push');
            app.h_std_delete_button.ButtonPushedFcn = createCallbackFcn(app, @h_std_delete_buttonButtonPushed, true);
            app.h_std_delete_button.Text = 'Delete Standard';
            app.h_unk_delete_button = uibutton(app.GridLayout, 'push');
            app.h_unk_delete_button.ButtonPushedFcn = createCallbackFcn(app, @h_unk_delete_buttonButtonPushed, true);
            app.h_unk_delete_button.Text = 'Delete Unknown';
            app.h_unk_copy_button = uibutton(app.GridLayout, 'push');
            app.h_unk_copy_button.ButtonPushedFcn = createCallbackFcn(app, @h_unk_copy_buttonButtonPushed, true);
            app.h_unk_copy_button.Text = 'Copy Unknown';
            app.h_currentSTD_SRM_out = uidropdown(app.GridLayout);
            app.h_currentSTD_SRM_out.Items = {'Assign SRM'};
            app.h_currentSTD_SRM_out.ValueChangedFcn = createCallbackFcn(app, @h_currentSTD_SRM_outValueChanged, true);
            app.h_currentSTD_SRM_out.Value = 'Assign SRM';
            app.h_currentSTD_popup = uilistbox(app.GridLayout);
            app.h_currentSTD_popup.Items = {};
            app.h_currentSTD_popup.ValueChangedFcn = createCallbackFcn(app, @h_currentSTD_popupValueChanged, true);
            app.h_currentSTD_popup.Value = {};
            app.h_currentUNK_popup = uilistbox(app.GridLayout);
            app.h_currentUNK_popup.Items = {};
            app.h_currentUNK_popup.ValueChangedFcn = createCallbackFcn(app, @h_currentUNK_popupValueChanged, true);
            app.h_currentUNK_popup.Value = {};
            app.h_CALC_manager = uibutton(app.GridLayout, 'push');
            app.h_CALC_manager.ButtonPushedFcn = createCallbackFcn(app, @h_CALC_managerButtonPushed, true);
            app.h_CALC_manager.Text = 'Calculation Settings';
            app.StandardSettingsLabel = uilabel(app.GridLayout);
            app.StandardSettingsLabel.Text = 'Standard Settings';
            app.UnknownSettingsLabel = uilabel(app.GridLayout);
            app.UnknownSettingsLabel.Text = 'Unknown Settings';
            app.h_currentSTD_driftmm_edit = uieditfield(app.GridLayout, 'numeric');
            app.h_currentSTD_driftmm_edit.ValueChangedFcn = createCallbackFcn(app, @h_currentSTD_drifthh_editValueChanged, true);
            app.h_currentSTD_drifthh_edit = uieditfield(app.GridLayout, 'numeric');
            app.h_currentSTD_drifthh_edit.ValueChangedFcn = createCallbackFcn(app, @h_currentSTD_drifthh_editValueChanged, true);
            app.HHMMLabel = uilabel(app.GridLayout);
            app.HHMMLabel.Text = 'HH:MM';
            app.CopyStandardButton = uibutton(app.GridLayout, 'push');
            app.CopyStandardButton.ButtonPushedFcn = createCallbackFcn(app, @CopyStandardButtonPushed, true);
            app.CopyStandardButton.Text = 'Copy Standard';
            app.ImportModuleButton = uibutton(app.GridLayout, 'push');
            app.ImportModuleButton.ButtonPushedFcn = createCallbackFcn(app, @ImportModuleButtonPushed, true);
            app.ImportModuleButton.Text = 'Import Module';
            app.Panel = uipanel(app.h_fig);
            app.Panel.Title = 'Panel';
            app.Panel.Visible = 'off';
            app.h_currentUNK_comp3from_out = uieditfield(app.Panel, 'numeric');
            app.h_currentUNK_comp2from_out = uieditfield(app.Panel, 'numeric');
            app.h_currentUNK_comp3to_out = uieditfield(app.Panel, 'numeric');
            app.h_currentUNK_comp2to_out = uieditfield(app.Panel, 'numeric');
            app.h_currentUNK_comp1from_out = uieditfield(app.Panel, 'numeric');
            app.h_currentUNK_comp1to_out = uieditfield(app.Panel, 'numeric');
            app.h_currentUNK_mat2from_out = uieditfield(app.Panel, 'numeric');
            app.h_currentUNK_mat2to_out = uieditfield(app.Panel, 'numeric');
            app.h_currentUNK_mat1from_out = uieditfield(app.Panel, 'numeric');
            app.h_currentUNK_mat1to_out = uieditfield(app.Panel, 'numeric');
            app.h_currentUNK_bgfrom_out = uieditfield(app.Panel, 'numeric');
            app.h_currentUNK_bgto_out = uieditfield(app.Panel, 'numeric');
            app.h_currentUNK_comp3total_out = uieditfield(app.Panel, 'numeric');
            app.h_currentUNK_comp2total_out = uieditfield(app.Panel, 'numeric');
            app.h_currentUNK_comp1total_out = uieditfield(app.Panel, 'numeric');
            app.h_currentUNK_mat2total_out = uieditfield(app.Panel, 'numeric');
            app.h_currentUNK_mat1total_out = uieditfield(app.Panel, 'numeric');
            app.h_currentUNK_bgtotal_out = uieditfield(app.Panel, 'numeric');
            app.SignalLabel = uilabel(app.Panel);
            app.SignalLabel.Text = 'Signal';
            app.BkgdLabel = uilabel(app.Panel);
            app.BkgdLabel.Text = 'Bkgd';
            app.h_currentSTD_sigtotal_out = uieditfield(app.Panel, 'numeric');
            app.h_currentSTD_sigto_out = uieditfield(app.Panel, 'numeric');
            app.h_currentSTD_driftpoints_popup = uidropdown(app.Panel);
            app.h_currentSTD_driftpoints_popup.Items = {'WTF is this'};
            app.h_currentSTD_driftpoints_popup.Value = 'WTF is this';
            app.totalEditFieldLabel = uilabel(app.Panel);
            app.totalEditFieldLabel.Text = 'total:';
            app.h_currentSTD_bgtotal_out = uieditfield(app.Panel, 'numeric');
            app.toEditFieldLabel = uilabel(app.Panel);
            app.toEditFieldLabel.Text = 'to:';
            app.h_currentSTD_bgto_out = uieditfield(app.Panel, 'numeric');
            app.h_currentSTD_sigfrom_out = uieditfield(app.Panel, 'numeric');
            app.fromEditFieldLabel = uilabel(app.Panel);
            app.fromEditFieldLabel.Text = 'from:';
            app.h_currentSTD_bgfrom_out = uieditfield(app.Panel, 'numeric');
            app.LASAButton = uibutton(app.Panel, 'push');
            app.LASAButton.ButtonPushedFcn = createCallbackFcn(app, @LASAButtonPushed, true);
            app.LASAButton.Text = 'LASA';
            app.FilenameEditFieldLabel_2 = uilabel(app.Panel);
            app.FilenameEditFieldLabel_2.Text = 'Filename';
            app.h_currentUNK_filename_out = uieditfield(app.Panel, 'text');
            app.FilenameEditFieldLabel = uilabel(app.Panel);
            app.FilenameEditFieldLabel.Text = 'Filename';
            app.h_currentSTD_filename_out = uieditfield(app.Panel, 'text');
            app.h_currentUNK_mattotal_out = uieditfield(app.Panel, 'numeric');
            app.h_currentUNK_sigtotal_out = uieditfield(app.Panel, 'numeric');
            % Show the figure after all components are created
            app.h_fig.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SILLS

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.h_fig)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.h_fig)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.h_fig)
        end
    end
end