classdef calculation_manager < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        calculationmanagerUIFigure  matlab.ui.Figure
        report                      matlab.ui.container.Menu
        report_settings             matlab.ui.container.Menu
        majors_as_oxides            matlab.ui.container.Menu
        ratios                      matlab.ui.container.Menu
        ratios_elements             matlab.ui.container.Menu
        LOD                         matlab.ui.container.Menu
        method                      matlab.ui.container.Menu
        pettke                      matlab.ui.container.Menu
        longerich                   matlab.ui.container.Menu
        report_write                matlab.ui.container.Menu
        calibration                 matlab.ui.container.Menu
        calibration_show            matlab.ui.container.Menu
        GridLayout2                 matlab.ui.container.GridLayout
        Panel                       matlab.ui.container.Panel
        GridLayout                  matlab.ui.container.GridLayout
        h_CPSonly                   matlab.ui.control.CheckBox
        DeleteButton                matlab.ui.control.Button
        CopyButton                  matlab.ui.control.Button
        PlotButton                  matlab.ui.control.Button
        DefineSampleSettingsButton  matlab.ui.control.Button
        ElementsButton              matlab.ui.control.Button
        ApplyToAllButton            matlab.ui.control.Button
        DevButtonTableUpdate        matlab.ui.control.Button
        UITable                     matlab.ui.control.Table
    end


    properties (Access = private)
        click_time % Description
    end
    
    properties (Access = public)
        SILLS_X % Description
    end

    methods (Access = public)

        function update_table_fun(app)
            units_str={'ug/g';'wt.%'};
            mattype_str={'none';'internal std';'total oxides'};
            constraint_str={'internal standard';'wt% NaCl (mass)';'wt% NaCl (charge)';'total oxides (majors)'};

            %% creating the indvidual columns as vectors
            h_count(:,1)=1:app.SILLS_X.A.UNK_num;
            h_UNKfile=app.SILLS_X.A.UNKPOPUPLIST;
            h_Info={app.SILLS_X.UNK.Info}';


            h_TIMEPOINT =   {app.SILLS_X.UNK.timepoint}';
            h_TIMEhh =      {app.SILLS_X.UNK.hh}';
            h_TIMEmm =      {app.SILLS_X.UNK.mm}';


            h_MATtype =     mattype_str([app.SILLS_X.UNK.MAT_corrtype]);
            h_MATfile =app.SILLS_X.A.UNK_with_matrix([app.SILLS_X.UNK.MATcorrfile_index]);
            % h_MATfile =app.SILLS_X.A.UNK_with_matrix([app.SILLS_X.UNK.MATcorrfile_index])

            h_MATconc={app.SILLS_X.UNK.MATQIS_conc}';

            h_MATunit=units_str([app.SILLS_X.UNK.MATunit]); % {'ug/g';'wt.%'} %ATH check here if a value is stored somewhere
            h_MATint=app.SILLS_X.A.ISOTOPES_in_all_SRMs([app.SILLS_X.UNK.MATQISiso])'; % app.SILLS_X.A.ISOTOPES_in_all_SRMs


            if app.SILLS_X.A.Oxide_test~= 0
                %  h_MATconcwt =  uicontrol(SMAN.h_SMAN,'style','edit','Userdata',c,'Callback','MATINTSTD','string',UNK.MATQIS_concwt,'BackgroundColor',[.9 .9 .9],'ForegroundColor',[0 0 0],'HorizontalAlignment','right','position',[555*sf(1) (885-20*c)*sf(2) 60*sf(1) 20*sf(2)]);
                h_MATconcwt =  {app.SILLS_X.UNK.MATQIS_concwt}';
                % h_MATintox =    uicontrol(SMAN.h_SMAN,'style','popup','Userdata',c,'Callback','MATINTSTD','string',A.OXIDES_in_all_SRMs,'Value',UNK.MATQISox,'BackgroundColor',[.9 .9 .9],'ForegroundColor',[0 0 0],'HorizontalAlignment','right','position',[680*sf(1) (885-20*c)*sf(2) 60*sf(1) 20*sf(2)]);
                %h_MATintox={app.SILLS_X.UNK.MATQISox}'
                h_MATintox=app.SILLS_X.A.OXIDES_in_all_SRMs([app.SILLS_X.UNK.MATQISox])';

                % h_MAToxide =    uicontrol(SMAN.h_SMAN,'style','edit','Userdata',c,'Callback',MATINTSTD_oxide,'string',UNK.MAT_oxide_total,'BackgroundColor',[0 0 0],'ForegroundColor',[1 1 1],'HorizontalAlignment','right','position',[555*sf(1) (885-20*c)*sf(2) 60*sf(1) 20*sf(2)]);
                h_MAToxide ={app.SILLS_X.UNK.MAT_oxide_total}';
            else
                h_MATconcwt=cell(app.SILLS_X.A.UNK_num,1);
                h_MATintox=cell(app.SILLS_X.A.UNK_num,1);
                h_MAToxide=cell(app.SILLS_X.A.UNK_num,1);
            end

            if app.SILLS_X.A.Fe_test ~= 0
                h_MAT_Feratio ={app.SILLS_X.UNK.MAT_Fe_ratio}';
            else
                h_MAT_Feratio=cell(app.SILLS_X.A.UNK_num,1);

            end
            %h_MATAPPLY2ALL =        uicontrol(SMAN.h_SMAN,'style','pushbutton','Callback','MATAPPLY2ALL','UserData',c,'string','Apply to All','position',[750*sf(1) (885-20*c)*sf(2) 70*sf(1) 20*sf(2)]);

            h_SIGconstraint1_popup(1:app.SILLS_X.A.UNK_num,1)=constraint_str([app.SILLS_X.UNK.SIG_constraint1]);%ATH check here if a value is stored somewhere {'internal standard';'wt% NaCl (mass)';'wt% NaCl (charge)';'total oxides (majors)'};
            h_SIG1concis={app.SILLS_X.UNK.SIGQIS1_conc}';

            h_SIG1unit(1:app.SILLS_X.A.UNK_num,1)=units_str([app.SILLS_X.UNK.SIG1unit]); %  %ATH check here if a value is stored somewhere

            h_SIG1int=app.SILLS_X.A.ISOTOPES_in_all_SRMs([app.SILLS_X.UNK.SIGQIS1iso])';
            h_SIGsalt= {app.SILLS_X.UNK.SIGsalinity}';
            % h_SALT_set_button  uicontrol(SMAN.h_SMAN,'style','pushbutton','Userdata',c,'string','ELEMENTS','Callback','SALTSET','position',[1095*sf(1) (885-20*c)*sf(2) 60*sf(1) 20*sf(2)]);

            if app.SILLS_X.A.Fe_test ~= 0
                h_SIG1conciswt ={app.SILLS_X.UNK.SIGQIS1_concwt}';
                h_SIG1intox =    app.SILLS_X.A.OXIDES_in_all_SRMs([app.SILLS_X.UNK.SIGQIS1ox])';
                h_SIGoxide =  {app.SILLS_X.UNK.SIG_oxide_total}';
            else
                h_SIG1conciswt =cell(app.SILLS_X.A.UNK_num,1);
                h_SIG1intox =cell(app.SILLS_X.A.UNK_num,1);
                h_SIGoxide =cell(app.SILLS_X.A.UNK_num,1);
            end

            if app.SILLS_X.A.Fe_test ~= 0
                h_SIG_Feratio =     {app.SILLS_X.UNK.SIG_Fe_ratio}';
            else
                h_SIG_Feratio=cell(app.SILLS_X.A.UNK_num,1);
            end

            %  SMAN.handles(c).h_SIGAPPLY2ALL =        uicontrol(SMAN.h_SMAN,'style','pushbutton','Callback','SIGAPPLY2ALL','UserData',c,'string','Apply to All','position',[1165*sf(1) (885-20*c)*sf(2) 70*sf(1) 20*sf(2)]);
            %
            %     %create a signal quantification button to be shown when there are two
            %     SMAN.handles(c).h_SIGquantbutton =      uicontrol(SMAN.h_SMAN,'style','pushbutton','Userdata',c,'Callback','SIGQUANT_SETTINGS','string','DEFINE SAMPLE SETTINGS','HorizontalAlignment','center','position',[940*sf(1) (885-20*c)*sf(2) 200*sf(1) 20*sf(2)]);
h_SIGquantbutton(1:app.SILLS_X.A.UNK_num,1)={''};

h_SIGquantbutton([app.SILLS_X.UNK.sigtotal]'>0&([app.SILLS_X.UNK.MAT_corrtype]'==2 |[app.SILLS_X.UNK.MAT_corrtype]'==3))={'DEFINE SAMPLE SETTINGS'};

            h_nosigwarning([app.SILLS_X.UNK.sigtotal]>0,1)={''};
            h_nosigwarning(not([app.SILLS_X.UNK.sigtotal]>0),1)={'No Signal'};

            %% set specific values here depending on calculation settings:

            %%
            %% new test nils 20240902
            MAT_corrtype=[app.SILLS_X.UNK.MAT_corrtype]'; % %corrtypes={'no matrix correction','internal standard','total oxides'}


T=table(h_count,h_UNKfile,h_Info,h_TIMEPOINT,h_TIMEhh,h_TIMEmm,h_MATtype,h_MATfile,...
    h_MATconc,h_MATunit,h_MATint,h_MATconcwt,h_MATintox,h_MAToxide,h_MAT_Feratio...
    ,h_SIGconstraint1_popup,h_SIG1concis,h_SIG1unit,h_SIG1int,h_SIGsalt,...
    h_SIG1conciswt,h_SIG1intox,h_SIGoxide,h_SIG_Feratio,h_SIGquantbutton,h_nosigwarning);
VarNames = T.Properties.VariableNames;

style_edit_matrix=false(size(T));

style_edit_matrix(MAT_corrtype==1,strcmp(VarNames,'h_MATfile'))=true;
style_edit_matrix(MAT_corrtype==1,strcmp(VarNames,'h_MATint'))=true;
style_edit_matrix(MAT_corrtype==1,strcmp(VarNames,'h_MATconc'))=true;
style_edit_matrix(MAT_corrtype==1,strcmp(VarNames,'h_MATunit'))=true;

[h_MATfile{MAT_corrtype==1}]=deal('');
[h_MATint{MAT_corrtype==1}]=deal('');
[h_MATconc{MAT_corrtype==1}]=deal([]);
[h_MATunit{MAT_corrtype==1}]=deal('');


[h_MATunit{MAT_corrtype==1}]=deal('');


style_edit_matrix(MAT_corrtype==1&app.SILLS_X.A.Oxide_test ~= 0,strcmp(VarNames,'h_MATintox'))=true;
style_edit_matrix(MAT_corrtype==1&app.SILLS_X.A.Oxide_test ~= 0,strcmp(VarNames,'h_MATconcwt'))=true;
style_edit_matrix(MAT_corrtype==1&app.SILLS_X.A.Oxide_test ~= 0,strcmp(VarNames,'h_MAToxide'))=true;

[h_MATintox{MAT_corrtype==1&app.SILLS_X.A.Oxide_test ~= 0}]=deal('');
[h_MATconcwt{MAT_corrtype==1&app.SILLS_X.A.Oxide_test ~= 0}]=deal([]);
[h_MAToxide{MAT_corrtype==1&app.SILLS_X.A.Oxide_test ~= 0}]=deal([]);


%
%[h_SIGconstraint1_popup{MAT_corrtype==1}]=deal('');
%MAT_corrtype == 1&[app.SILLS_X.UNK.sigtotal]'>0

% h_SIG1int{MAT_corrtype == 1&[app.SILLS_X.UNK.sigtotal]'>0&[app.SILLS_X.UNK.SIG_constraint1]'==1&[app.SILLS_X.UNK.SIG1unit]'  == 1}=constraint_str{app.SILLS_X.UNK(logic_test).SIG_constraint1};
% h_SIG1concis{MAT_corrtype == 1&[app.SILLS_X.UNK.sigtotal]'>0&[app.SILLS_X.UNK.SIG_constraint1]'==1&[app.SILLS_X.UNK.SIG1unit]'  == 1}=app.SILLS_X.UNK(logic_test).SIGQIS1_conc;

[h_SIG1intox{MAT_corrtype == 1&[app.SILLS_X.UNK.sigtotal]'>0&[app.SILLS_X.UNK.SIG_constraint1]'==1&[app.SILLS_X.UNK.SIG1unit]'  == 1&app.SILLS_X.A.Oxide_test}]=deal('');
[h_SIG1conciswt{MAT_corrtype == 1&[app.SILLS_X.UNK.sigtotal]'>0&[app.SILLS_X.UNK.SIG_constraint1]'==1&[app.SILLS_X.UNK.SIG1unit]'  == 1&app.SILLS_X.A.Oxide_test}]=deal([]);
style_edit_matrix(MAT_corrtype == 1&[app.SILLS_X.UNK.sigtotal]'>0&[app.SILLS_X.UNK.SIG_constraint1]'==1&[app.SILLS_X.UNK.SIG1unit]'  == 1&app.SILLS_X.A.Oxide_test,strcmp(VarNames,'h_SIG1intox'))=true;
style_edit_matrix(MAT_corrtype == 1&[app.SILLS_X.UNK.sigtotal]'>0&[app.SILLS_X.UNK.SIG_constraint1]'==1&[app.SILLS_X.UNK.SIG1unit]'  == 1&app.SILLS_X.A.Oxide_test,strcmp(VarNames,'h_SIG1conciswt'))=true;


[h_SIG1int{MAT_corrtype == 1&[app.SILLS_X.UNK.sigtotal]'>0&[app.SILLS_X.UNK.SIG_constraint1]'==1&[app.SILLS_X.UNK.SIG1unit]'  == 2}]=deal('');
[h_SIG1concis{MAT_corrtype == 1&[app.SILLS_X.UNK.sigtotal]'>0&[app.SILLS_X.UNK.SIG_constraint1]'==1&[app.SILLS_X.UNK.SIG1unit]'  == 2}]=deal([]);

style_edit_matrix(MAT_corrtype == 1&[app.SILLS_X.UNK.sigtotal]'>0&[app.SILLS_X.UNK.SIG_constraint1]'==1&[app.SILLS_X.UNK.SIG1unit]' == 2,strcmp(VarNames,'h_SIG1int'))=true;
style_edit_matrix(MAT_corrtype == 1&[app.SILLS_X.UNK.sigtotal]'>0&[app.SILLS_X.UNK.SIG_constraint1]'==1&[app.SILLS_X.UNK.SIG1unit]'  == 2,strcmp(VarNames,'h_SIG1concis'))=true;

[h_SIGsalt{MAT_corrtype == 1&[app.SILLS_X.UNK.sigtotal]'>0&[app.SILLS_X.UNK.SIG_constraint1]'==1&[app.SILLS_X.UNK.SIG1unit]'  == 2}]=deal([]);
style_edit_matrix(MAT_corrtype == 1&[app.SILLS_X.UNK.sigtotal]'>0&[app.SILLS_X.UNK.SIG_constraint1]'==1&[app.SILLS_X.UNK.SIG1unit]'  == 2,strcmp(VarNames,'h_SIGsalt'))=true;
%style_edit_matrix(MAT_corrtype == 1&[app.SILLS_X.UNK.sigtotal]'>0&[app.SILLS_X.UNK.SIG_constraint1]'==1&[app.SILLS_X.UNK.SIG1unit]'  == 2,strcmp(VarNames,'h_SALT_set_button'))=true;

%set(SMAN.handles(c).h_SIGsalt,'Visible','off');
%    set(SMAN.handles(c).h_SALT_set_button,'Visible','off');

[h_SIGsalt{MAT_corrtype == 1&[app.SILLS_X.UNK.sigtotal]'>0}]=deal([]);
style_edit_matrix(MAT_corrtype == 1&[app.SILLS_X.UNK.sigtotal]'>0&[app.SILLS_X.UNK.SIG_constraint1]'==1&[app.SILLS_X.UNK.SIG1unit]'  == 2,strcmp(VarNames,'h_SIGsalt'))=true;

[h_SIGoxide{MAT_corrtype == 1&[app.SILLS_X.UNK.sigtotal]'>0&app.SILLS_X.A.Oxide_test~= 0}]=deal([]);
style_edit_matrix(MAT_corrtype == 1&[app.SILLS_X.UNK.sigtotal]'>0&app.SILLS_X.A.Oxide_test~= 0,strcmp(VarNames,'h_SIGoxide'))=true;


%   set(SMAN.handles(c).h_SIGquantbutton,'Visible','off');

[h_nosigwarning{MAT_corrtype == 1&[app.SILLS_X.UNK.sigtotal]'>0}]=deal('');
style_edit_matrix(MAT_corrtype == 1&[app.SILLS_X.UNK.sigtotal]'>0,strcmp(VarNames,'h_nosigwarning'))=true;

[h_SIG_Feratio{MAT_corrtype == 1&[app.SILLS_X.UNK.sigtotal]'>0&[app.SILLS_X.A.Fe_test]'~= 0}]=deal([]);
style_edit_matrix(MAT_corrtype == 1&[app.SILLS_X.UNK.sigtotal]'>0&[app.SILLS_X.A.Fe_test]'~= 0,strcmp(VarNames,'h_SIG_Feratio'))=true;


%else sig<0

[h_SIGconstraint1_popup{MAT_corrtype == 1&~[app.SILLS_X.UNK.sigtotal]'>0}]=deal('');
[h_SIG1int{MAT_corrtype == 1&~[app.SILLS_X.UNK.sigtotal]'>0}]=deal('');
[h_SIG1concis{MAT_corrtype == 1&~[app.SILLS_X.UNK.sigtotal]'>0}]=deal([]);
[h_SIGsalt{MAT_corrtype == 1&~[app.SILLS_X.UNK.sigtotal]'>0}]=deal([]);
[h_SIG1unit{MAT_corrtype == 1&~[app.SILLS_X.UNK.sigtotal]'>0}]=deal('');
%[h_nosigwarning{MAT_corrtype == 1&~[app.SILLS_X.UNK.sigtotal]'>0}]=deal('');

style_edit_matrix(MAT_corrtype == 1&~[app.SILLS_X.UNK.sigtotal]'>0,strcmp(VarNames,'h_SIGconstraint1_popup'))=true;
style_edit_matrix(MAT_corrtype == 1&~[app.SILLS_X.UNK.sigtotal]'>0,strcmp(VarNames,'h_SIG1int'))=true;
style_edit_matrix(MAT_corrtype == 1&~[app.SILLS_X.UNK.sigtotal]'>0,strcmp(VarNames,'h_SIG1concis'))=true;
style_edit_matrix(MAT_corrtype == 1&~[app.SILLS_X.UNK.sigtotal]'>0,strcmp(VarNames,'h_SIGsalt'))=true;
style_edit_matrix(MAT_corrtype == 1&~[app.SILLS_X.UNK.sigtotal]'>0,strcmp(VarNames,'h_SIG1unit'))=true;

[h_SIG1intox{MAT_corrtype == 1&~[app.SILLS_X.UNK.sigtotal]'>0&app.SILLS_X.A.Oxide_test~= 0}]=deal('');
[h_SIG1conciswt{MAT_corrtype == 1&~[app.SILLS_X.UNK.sigtotal]'>0&app.SILLS_X.A.Oxide_test~= 0}]=deal([]);
[h_SIGoxide{MAT_corrtype == 1&~[app.SILLS_X.UNK.sigtotal]'>0&app.SILLS_X.A.Oxide_test~= 0}]=deal([]);

style_edit_matrix(MAT_corrtype == 1&~[app.SILLS_X.UNK.sigtotal]'>0&app.SILLS_X.A.Oxide_test~= 0,strcmp(VarNames,'h_SIG1intox'))=true;
style_edit_matrix(MAT_corrtype == 1&~[app.SILLS_X.UNK.sigtotal]'>0&app.SILLS_X.A.Oxide_test~= 0,strcmp(VarNames,'h_SIG1conciswt'))=true;
style_edit_matrix(MAT_corrtype == 1&~[app.SILLS_X.UNK.sigtotal]'>0&app.SILLS_X.A.Oxide_test~= 0,strcmp(VarNames,'h_SIGoxide'))=true;


[h_MAT_Feratio{MAT_corrtype == 1&[app.SILLS_X.A.Fe_test]'~= 0}]=deal([]);
style_edit_matrix(MAT_corrtype == 1&[app.SILLS_X.A.Fe_test]'~= 0,strcmp(VarNames,'h_MAT_Feratio'))=true;
% das hier fehlt noch 
% if corrtype == 1 %i.e. no matrix correction
% 
%     if UNK(c).sigtotal > 0 %i.e. a signal window exists
%         if UNK(c).SIG_constraint1 == 1 %i.e. internal standard
% 
%             set(SMAN.handles(c).h_SIGsalt,'Visible','off');
%             set(SMAN.handles(c).h_SALT_set_button,'Visible','off');
%             if A.Oxide_test ~= 0
%                 set(SMAN.handles(c).h_SIGoxide,'Visible','off');
%             end
%             set(SMAN.handles(c).h_SIGAPPLY2ALL,'Visible','on');
%             set(SMAN.handles(c).h_SIGquantbutton,'Visible','off');
%             set(SMAN.handles(c).h_nosigwarning,'visible','off');
%             if A.Fe_test ~= 0;
%                 set(SMAN.handles(c).h_SIG_Feratio,'visible','off');
%             end
%         elseif UNK(c).SIG_constraint1 == 2 || UNK(c).SIG_constraint1 == 3 %i.e. salinity
%             set(SMAN.handles(c).h_SIGconstraint1_popup,'Visible','on','value',UNK(c).SIG_constraint1);
%             set(SMAN.handles(c).h_SIG1int,'Visible','off');
%             set(SMAN.handles(c).h_SIG1concis,'Visible','off');
%             set(SMAN.handles(c).h_SIGsalt,'Visible','on','string',UNK(c).SIGsalinity);
%             set(SMAN.handles(c).h_SIG1unit,'Visible','on','value',2);
%             set(SMAN.handles(c).h_nosigwarning,'visible','off');
%             if A.Oxide_test ~= 0
%                 set(SMAN.handles(c).h_SIG1intox,'Visible','off');
%                 set(SMAN.handles(c).h_SIG1conciswt,'Visible','off');
%                 set(SMAN.handles(c).h_SIGoxide,'Visible','off');
%             end
%             if A.Fe_test ~= 0;
%                 set(SMAN.handles(c).h_SIG_Feratio,'visible','off');
%             end
%         elseif UNK(c).SIG_constraint1 == 4 %i.e. total oxide
%             set(SMAN.handles(c).h_SIGconstraint1_popup,'Visible','on','value',UNK(c).SIG_constraint1);
%             set(SMAN.handles(c).h_SIG1int,'Visible','off');
%             set(SMAN.handles(c).h_SIG1concis,'Visible','off');
%             set(SMAN.handles(c).h_SIGsalt,'Visible','off');
%             set(SMAN.handles(c).h_SALT_set_button,'Visible','off');
%             set(SMAN.handles(c).h_SIG1unit,'Visible','on','value',2);
%             set(SMAN.handles(c).h_SIGquantbutton,'Visible','off');
%             set(SMAN.handles(c).h_nosigwarning,'visible','off');
%             if A.Oxide_test ~= 0
%                 set(SMAN.handles(c).h_SIG1intox,'Visible','off');
%                 set(SMAN.handles(c).h_SIG1conciswt,'Visible','off');
%                 set(SMAN.handles(c).h_SIGoxide,'Visible','on','string',UNK(c).SIG_oxide_total);
%             end
%             if A.Fe_test ~= 0;
%                 set(SMAN.handles(c).h_SIG_Feratio,'visible','on','value',UNK(c).SIG_Fe_ratio);
%             end
%         end
%     else
% 
% 
%     end
% 
% elseif corrtype == 2 %internal standard
% 
% 
% %     [h_SIGconstraint1_popup{MAT_corrtype==2}]=deal('');
% %     style_edit_matrix(MAT_corrtype==2,strcmp(VarNames,'h_SIGconstraint1_popup'))=true;
%   
%     if UNK(c).sigtotal > 0
%         set(SMAN.handles(c).h_SIGquantbutton,'Visible','on');
%         set(SMAN.handles(c).h_nosigwarning,'visible','off');
%     else
%         set(SMAN.handles(c).h_SIGquantbutton,'Visible','off');
%         set(SMAN.handles(c).h_nosigwarning,'visible','on');
%     end
%     if A.Fe_test ~= 0;
%         set(SMAN.handles(c).h_MAT_Feratio,'visible','off');
%         set(SMAN.handles(c).h_SIG_Feratio,'visible','off');
%     end
% elseif corrtype ==3 %total oxides
% 
% 
% 
% end


% elseif corrtype == 2 %internal standard


[h_SIGconstraint1_popup{MAT_corrtype==2}]=deal('');
[h_SIG1int{MAT_corrtype==2}]=deal('');
[h_SIG1concis{MAT_corrtype==2}]=deal([]);
[h_SIGsalt{MAT_corrtype==2}]=deal([]);
[h_SIG1unit{MAT_corrtype==2}]=deal('');


style_edit_matrix(MAT_corrtype==2,strcmp(VarNames,'h_SIGconstraint1_popup'))=true;
style_edit_matrix(MAT_corrtype==2,strcmp(VarNames,'h_SIG1int'))=true;
style_edit_matrix(MAT_corrtype==2,strcmp(VarNames,'h_SIG1concis'))=true;
style_edit_matrix(MAT_corrtype==2,strcmp(VarNames,'h_SIGsalt'))=true;
style_edit_matrix(MAT_corrtype==2,strcmp(VarNames,'h_SIG1unit'))=true;


    [h_MATintox{MAT_corrtype==2&[app.SILLS_X.UNK.MATunit]'==1&app.SILLS_X.A.Oxide_test ~= 0}]=deal('');
    [h_MATconcwt{MAT_corrtype==2&[app.SILLS_X.UNK.MATunit]'==1&app.SILLS_X.A.Oxide_test ~= 0}]=deal([]);

    style_edit_matrix(MAT_corrtype==2&[app.SILLS_X.UNK.MATunit]'==1&app.SILLS_X.A.Oxide_test ~= 0,strcmp(VarNames,'h_MATconcwt'))=true;
    style_edit_matrix(MAT_corrtype==2&[app.SILLS_X.UNK.MATunit]'==1&app.SILLS_X.A.Oxide_test ~= 0,strcmp(VarNames,'h_MATconcwt'))=true;


    [h_MATint{MAT_corrtype==2&[app.SILLS_X.UNK.MATunit]'==2&app.SILLS_X.A.Oxide_test ~= 0}]=deal('');
    [h_MATconc{MAT_corrtype==2&[app.SILLS_X.UNK.MATunit]'==2&app.SILLS_X.A.Oxide_test ~= 0}]=deal([]);

    style_edit_matrix(MAT_corrtype==2&[app.SILLS_X.UNK.MATunit]'==2&app.SILLS_X.A.Oxide_test ~= 0,strcmp(VarNames,'h_MATint'))=true;
    style_edit_matrix(MAT_corrtype==2&[app.SILLS_X.UNK.MATunit]'==2&app.SILLS_X.A.Oxide_test ~= 0,strcmp(VarNames,'h_MATconc'))=true;

    [h_MAToxide{MAT_corrtype==2&app.SILLS_X.A.Oxide_test ~= 0}]=deal([]);
    style_edit_matrix(MAT_corrtype==2&app.SILLS_X.A.Oxide_test ~= 0,strcmp(VarNames,'h_MAToxide'))=true;

    [h_SIG1intox{MAT_corrtype==2&app.SILLS_X.A.Oxide_test ~= 0}]=deal([]);
    style_edit_matrix(MAT_corrtype==2&app.SILLS_X.A.Oxide_test ~= 0,strcmp(VarNames,'h_SIG1intox'))=true;


    [h_SIG1conciswt{MAT_corrtype==2&app.SILLS_X.A.Oxide_test ~= 0}]=deal([]);
    style_edit_matrix(MAT_corrtype==2&app.SILLS_X.A.Oxide_test ~= 0,strcmp(VarNames,'h_SIG1conciswt'))=true;

[h_SIGoxide{MAT_corrtype==2&app.SILLS_X.A.Oxide_test ~= 0}]=deal([]);
    style_edit_matrix(MAT_corrtype==2&app.SILLS_X.A.Oxide_test ~= 0,strcmp(VarNames,'h_SIGoxide'))=true;


% elseif corrtype ==3 %total oxides

[h_MATint{MAT_corrtype==3}]=deal('');
[h_MATconc{MAT_corrtype==3}]=deal('');
[h_SIGconstraint1_popup{MAT_corrtype==3}]=deal('');

[h_SIG1int{MAT_corrtype==3}]=deal('');
[h_SIG1concis{MAT_corrtype==3}]=deal([]);
[h_SIGsalt{MAT_corrtype==3}]=deal([]);
[h_SIG1unit{MAT_corrtype==3}]=deal('');

style_edit_matrix(MAT_corrtype==3,strcmp(VarNames,'h_MATint'))=true;
style_edit_matrix(MAT_corrtype==3,strcmp(VarNames,'h_MATconc'))=true;
style_edit_matrix(MAT_corrtype==3,strcmp(VarNames,'h_SIGconstraint1_popup'))=true;
style_edit_matrix(MAT_corrtype==3,strcmp(VarNames,'h_SIG1int'))=true;
style_edit_matrix(MAT_corrtype==3,strcmp(VarNames,'h_SIG1concis'))=true;
style_edit_matrix(MAT_corrtype==3,strcmp(VarNames,'h_SIGsalt'))=true;
style_edit_matrix(MAT_corrtype==3,strcmp(VarNames,'h_SIG1unit'))=true;
%
[h_MATintox{MAT_corrtype==3&app.SILLS_X.A.Oxide_test~= 0}]=deal('');
[h_MATconcwt{MAT_corrtype==3&app.SILLS_X.A.Oxide_test~= 0}]=deal([]);
[h_SIG1intox{MAT_corrtype==3&app.SILLS_X.A.Oxide_test~= 0}]=deal('');
[h_SIG1conciswt{MAT_corrtype==3&app.SILLS_X.A.Oxide_test~= 0}]=deal([]);
[h_SIGoxide{MAT_corrtype==3&app.SILLS_X.A.Oxide_test~= 0}]=deal([]);

style_edit_matrix(MAT_corrtype==3&app.SILLS_X.A.Oxide_test~= 0,strcmp(VarNames,'h_MATintox'))=true;
style_edit_matrix(MAT_corrtype==3&app.SILLS_X.A.Oxide_test~= 0,strcmp(VarNames,'h_MATconcwt'))=true;
style_edit_matrix(MAT_corrtype==3&app.SILLS_X.A.Oxide_test~= 0,strcmp(VarNames,'h_SIG1intox'))=true;
style_edit_matrix(MAT_corrtype==3&app.SILLS_X.A.Oxide_test~= 0,strcmp(VarNames,'h_SIG1conciswt'))=true;
style_edit_matrix(MAT_corrtype==3&app.SILLS_X.A.Oxide_test~= 0,strcmp(VarNames,'h_SIGoxide'))=true;
%
[h_nosigwarning{MAT_corrtype==3&[app.SILLS_X.A.Fe_test]'>0}]=deal('');
style_edit_matrix(MAT_corrtype==3&[app.SILLS_X.UNK.sigtotal]'>0,strcmp(VarNames,'h_nosigwarning'))=true;

[h_SIG_Feratio{MAT_corrtype==3&app.SILLS_X.A.Fe_test~= 0}]=deal('');
style_edit_matrix(MAT_corrtype==3&app.SILLS_X.A.Fe_test~= 0,strcmp(VarNames,'h_SIG_Feratio'))=true;

%

T=table(h_count,h_UNKfile,h_Info,h_TIMEPOINT,h_TIMEhh,h_TIMEmm,h_MATtype,h_MATfile,...
    h_MATconc,h_MATunit,h_MATint,h_MATconcwt,h_MATintox,h_MAToxide,h_MAT_Feratio...
    ,h_SIGconstraint1_popup,h_SIG1concis,h_SIG1unit,h_SIG1int,h_SIGsalt,...
    h_SIG1conciswt,h_SIG1intox,h_SIGoxide,h_SIG_Feratio,h_SIGquantbutton,h_nosigwarning);


% assigning Data as cell to UITable
VarNames = T.Properties.VariableNames;
app.UITable.Data = table2cell(T);
app.UITable.ColumnName = VarNames;  %{'h_count','h_UNKfile','h_Info','h_TIMEPOINT','h_TIMEhh','h_TIMEmm','h_MATconc','h_MATunit','h_MATint','','','','','',''};

removeStyle(app.UITable)
s = uistyle('BackgroundColor',[.5 .5 .5]);
[x,y]=find((style_edit_matrix));
addStyle(app.UITable,s,'cell',[x,y])
            %% end test
%             %% combining all variables in a table
%             T=table(h_count,h_UNKfile,h_Info,h_TIMEPOINT,h_TIMEhh,h_TIMEmm,h_MATtype,h_MATfile,...
%                 h_MATconc,h_MATunit,h_MATint,h_MATconcwt,h_MATintox,h_MAToxide,h_MAT_Feratio...
%                 ,h_SIGconstraint1_popup,h_SIG1concis,h_SIG1unit,h_SIG1int,h_SIGsalt,...
%                 h_SIG1conciswt,h_SIG1intox,h_SIGoxide,h_SIG_Feratio,h_nosigwarning);
% 
%         
% 
%             %% assigning Data as cell to UITable
%             VarNames = T.Properties.VariableNames;
%             app.UITable.Data = table2cell(T); % conversion to cell so dropdown option works
%             % I am not sure i fully understand which variable is defining the
%             % calculation type or if there are multiple corrtype seems to be important

            VarsToKeep = {'h_count','h_UNKfile', 'h_TIMEhh', 'h_TIMEmm','h_Info','h_MATtype'}; % these are always here

            for c = 1:app.SILLS_X.A.UNK_num
                if app.SILLS_X.UNK(c).MAT_corrtype == 0
                    % All columns are active, fictional use case

                elseif app.SILLS_X.UNK(c).MAT_corrtype == 1
                    % No matrix correction, this is for Mineral analysis
                    if app.SILLS_X.UNK(c).sigtotal > 0
                        if app.SILLS_X.UNK(c).SIG_constraint1 == 1
                            VarsToKeep = union(VarsToKeep, {'h_SIGconstraint1_popup'});
                            if app.SILLS_X.UNK(c).SIG1unit == 1
                                VarsToKeep = union(VarsToKeep, {'h_SIG1int', 'h_SIG1concis', 'h_SIG1unit'});
                            elseif app.SILLS_X.UNK(c).SIG1unit == 2
                                if app.SILLS_X.A.Oxide_test ~= 0
                                    VarsToKeep = union(VarsToKeep, {'h_SIG1intox', 'h_SIG1conciswt', 'h_SIG1unit'});
                                end
                            end
                        elseif app.SILLS_X.UNK(c).SIG_constraint1 == 2 || app.SILLS_X.UNK(c).SIG_constraint1 == 3
                            VarsToKeep = union(VarsToKeep, {'h_SIGconstraint1_popup','h_SIGsalt','h_SIG1unit'});
                        elseif app.SILLS_X.UNK(c).SIG_constraint1 == 4
                            VarsToKeep = union(VarsToKeep, {'h_SIGconstraint1_popup','h_SIG1unit'});
                            if app.SILLS_X.A.Oxide_test ~= 0
                                VarsToKeep = union(VarsToKeep, {'h_SIGoxide'});
                            end
                            if app.SILLS_X.A.Fe_test ~= 0
                                VarsToKeep = union(VarsToKeep, {'h_SIG_Feratio'});
                            end
                        end
                    end
                elseif app.SILLS_X.UNK(c).MAT_corrtype == 2
                    VarsToKeep = union(VarsToKeep, {'h_MATfile'});
                    if app.SILLS_X.UNK(c).MATunit == 1
                        VarsToKeep = union(VarsToKeep, {'h_MATint','h_MATconc'});
                    elseif app.SILLS_X.UNK(c).MATunit == 2
                        if app.SILLS_X.A.Oxide_test ~= 0
                            VarsToKeep = union(VarsToKeep, {'h_MATunit'});
                        end
                    end
                    if app.SILLS_X.A.Oxide_test ~= 0
                        VarsToKeep = union(VarsToKeep, {'h_MAToxide'});
                    end
                    VarsToKeep = union(VarsToKeep, {'h_MATunit'});
                elseif app.SILLS_X.UNK(c).MAT_corrtype == 3
                    VarsToKeep = union(VarsToKeep, {'h_MATfile', 'h_MATunit'});
                    if app.SILLS_X.A.Oxide_test ~= 0
                        VarsToKeep = union(VarsToKeep, {'h_MAToxide'});
                    end
                    if app.SILLS_X.A.Fe_test ~= 0
                        VarsToKeep = union(VarsToKeep, {'h_MAT_Feratio'});
                    end
                end
            end

if false % remove to filter columns
            T=T(:,ismember(VarNames,VarsToKeep)); % removing everything else from the table
            VarNames=VarNames(ismember(VarNames,VarsToKeep));% removing everything else from the Variable Names
end
            %% Adjusting the ColumnNames of UITable (these can be changed later)
            app.UITable.Data = table2cell(T);
            app.UITable.ColumnName = VarNames;  %{'h_count','h_UNKfile','h_Info','h_TIMEPOINT','h_TIMEhh','h_TIMEmm','h_MATconc','h_MATunit','h_MATint','','','','','',''};
            %app.UITable.ColumnFormat={[],{},{},{},{},{{'ug/g';'wt.%'}},{{app.SILLS_X.A.ISOTOPES_in_all_SRMs'}}
            %% adding the Dropdown Options


            app.UITable.ColumnFormat=cell(1,size(T,2));

            if any(ismember(VarNames,'h_MATtype'))
                app.UITable.ColumnFormat(:,strcmp(VarNames,'h_MATtype'))={{'none';'internal std';'total oxides'}'}; % makes a dopdown list with the elements {'ug/g';'wt.%'}
            end

            if any(ismember(VarNames,'h_MATfile'))
                app.UITable.ColumnFormat(:,strcmp(VarNames,'h_MATfile'))={app.SILLS_X.A.UNK_with_matrix'}; % makes a dopdown list with the elements {'ug/g';'wt.%'}
            end

            if any(ismember(VarNames,'h_MATunit')) || any(ismember(VarNames,'h_SIG1unit'))
                app.UITable.ColumnFormat(:,strcmp(VarNames,'h_MATunit')|strcmp(VarNames,'h_SIG1unit'))={{'ug/g';'wt.%'}'}; % makes a dopdown list with the elements {'ug/g';'wt.%'}
            end

            if any(ismember(VarNames,'h_MATint'))
                app.UITable.ColumnFormat(:,strcmp(VarNames,'h_MATint'))={app.SILLS_X.A.ISOTOPES_in_all_SRMs}; % makes a dopdown list with the elements in ISOTOPES_in_all_SRMs
            end

            if any(ismember(VarNames,'h_MATintox'))
                app.UITable.ColumnFormat(:,strcmp(VarNames,'h_MATintox'))={app.SILLS_X.A.OXIDES_in_all_SRMs}; % makes a dopdown list with the elements in ISOTOPES_in_all_SRM
            end

            if any(ismember(VarNames,'h_SIGconstraint1_popup'))
                app.UITable.ColumnFormat(:,strcmp(VarNames,'h_SIGconstraint1_popup'))={{'internal standard';'wt% NaCl (mass)';'wt% NaCl (charge)';'total oxides (majors)'}'}; % makes a dopdown list with the elements in ISOTOPES_in_all_SRM
            end

            if any(ismember(VarNames,'h_SIG1int'))
                app.UITable.ColumnFormat(:,strcmp(VarNames,'h_SIG1int'))={app.SILLS_X.A.ISOTOPES_in_all_SRMs}; % makes a dopdown list with the elements in ISOTOPES_in_all_SRM
            end

            app.UITable.ColumnEditable=true(1,size(T,2));%[false,false,true,true,true,true];


        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            app.SILLS_X=mainapp; %saving the mainapp as app.SILLS_X % now all Public variables in the mainapp can be acessed via app.SILLS_X.Variable
            update_table_fun(app) % function to update the Table. State will define later which type of calculation and which columns are visible.

        end

        % Button pushed function: DevButtonTableUpdate
        function DevButtonTableUpdatePushed(app, event)
            %state=1;
            update_table_fun(app)
        end

        % Menu selected function: report_write
        function report_writeMenuSelected(app, event)
            REPORT_WRITE_X % should work
        end

        % Menu selected function: calibration_show
        function calibration_showMenuSelected(app, event)
            CALIBPLOT%needs to be updated
        end

        % Menu selected function: majors_as_oxides
        function majors_as_oxidesMenuSelected(app, event)
            app.majors_as_oxides.Checked=not(app.majors_as_oxides.Checked);
            app.SILLS_X.A.report_settings_oxide=app.majors_as_oxides.Checked;

            REPORT_SETTINGS%needs to be updated

        end

        % Menu selected function: ratios
        function ratiosMenuSelected(app, event)
            %needs to be updated so callback works for children
            delete(app.ratios.Children)
            for b = 1:app.SILLS_X.A.ISOTOPES_in_all_SRMs_num
                %app.ratios_elements(b) = uimenu(app.ratios,'Label',char(app.SILLS_X.A.ISOTOPES_in_all_SRMs(b)),'Userdata',b,'Callback',ratios_elementsMenuSelected(app));
                app.ratios_elements(b) = uimenu(app.ratios,'Label',char(app.SILLS_X.A.ISOTOPES_in_all_SRMs(b)));
            end

            set(app.ratios_elements(:),'checked','off');
            set(app.ratios_elements(app.SILLS_X.A.REPORTIS_list_index),'checked','on');

        end

        % Menu selected function: ratios_elements
        function ratios_elementsMenuSelected(app, event)
            RATIOS %needs to be updated
        end

        % Menu selected function: LOD
        function LODMenuSelected(app, event)
            SETLODFF%needs to be updated
        end

        % Menu selected function: pettke
        function pettkeMenuSelected(app, event)
            app.SILLS_X.A.LODmethod = 'Pettke';
            app.pettke.Checked=true;
            app.longerich.Checked=false;


            setLODmethod%needs to be updated
        end

        % Menu selected function: longerich
        function longerichMenuSelected(app, event)
            app.SILLS_X.A.LODmethod = 'Longerich';
            app.pettke.Checked=false;
            app.longerich.Checked=true;

            setLODmethod%needs to be updated
        end

        % Callback function: UITable
        function UITableSelectionChanged(app, event)
            selection = app.UITable.Selection;
            if numel(unique(selection(:,1)))==1
                state=true;
            else
                state=false;

            end
            app.ApplyToAllButton.Enable=state;
            app.ElementsButton.Enable=state;
            app.DefineSampleSettingsButton.Enable=state;
            app.PlotButton.Enable=state;
            app.CopyButton.Enable=state;
            app.DeleteButton.Enable=state;
        end

        % Button pushed function: ElementsButton
        function ElementsButtonPushed(app, event)
            SALTSET%needs to be updated
        end

        % Value changed function: h_CPSonly
        function h_CPSonlyValueChanged(app, event)
            value = app.h_CPSonly.Value;
            if value
                CPSONLY
            end
        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;

            variable2change=app.UITable.ColumnName{indices(2)};
            id=app.UITable.Data{indices(1),1};

            switch variable2change
                case 'h_MATtype'
                    mattype_str={'none';'internal std';'total oxides'};
                    app.SILLS_X.UNK(id).MAT_corrtype=find(strcmp(newData,mattype_str));
                    %here an old update function needs to be implemented
                case 'h_MATfile'                    
            app.SILLS_X.UNK(id).MATcorrfile_index=find(strcmp(newData,app.SILLS_X.A.UNK_with_matrix));
                    %here an old update function needs to be implemented

            end
update_table_fun(app)
        end

        % Button down function: UITable
        function UITableButtonDown(app, event)
            if isempty(app.UITable.Selection) || numel(unique(app.UITable.Selection(:,2)))~=1 %|| column is selected that does not open a module
                return
            end

            click_threshold_s = 0.5; % double click time
            if isempty(app.click_time)
                app.click_time = tic(); % if its the first click start the clock
            else
                time_between_clicks = toc( app.click_time ); %check the time inbetween clicks
                app.click_time = tic();%  start the clock again
                if time_between_clicks < click_threshold_s
                    %% here we need to call the submodules

                    variable2change=app.UITable.ColumnName{app.UITable.Selection(1,2)};

                    switch variable2change
                        case 'h_SIGquantbutton'
                            % here open PopUp
                            Id=[app.UITable.Data{app.UITable.Selection(:,1),1}];
                            PopUp_Demo(app,app.SILLS_X,Id)

                    end

                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.calculationmanagerUIFigure = uifigure('Visible', 'off');
            app.calculationmanagerUIFigure.Name = 'calculation manager';
            app.report = uimenu(app.calculationmanagerUIFigure);
            app.report.Text = 'Report';
            app.report_settings = uimenu(app.report);
            app.report_settings.Text = 'Settings';
            app.majors_as_oxides = uimenu(app.report_settings);
            app.majors_as_oxides.MenuSelectedFcn = createCallbackFcn(app, @majors_as_oxidesMenuSelected, true);
            app.majors_as_oxides.Text = 'Major Elements as Oxides';
            app.ratios = uimenu(app.report_settings);
            app.ratios.MenuSelectedFcn = createCallbackFcn(app, @ratiosMenuSelected, true);
            app.ratios.Text = 'Show Ratios to Which Element?';
            app.ratios_elements = uimenu(app.ratios);
            app.ratios_elements.MenuSelectedFcn = createCallbackFcn(app, @ratios_elementsMenuSelected, true);
            app.ratios_elements.Text = 'e.g. Li7';
            app.LOD = uimenu(app.report_settings);
            app.LOD.MenuSelectedFcn = createCallbackFcn(app, @LODMenuSelected, true);
            app.LOD.Text = 'Set LOD filter factor';
            app.method = uimenu(app.report_settings);
            app.method.Text = 'Set LOD method';
            app.pettke = uimenu(app.method);
            app.pettke.MenuSelectedFcn = createCallbackFcn(app, @pettkeMenuSelected, true);
            app.pettke.Text = 'Pettke (2012)';
            app.longerich = uimenu(app.method);
            app.longerich.MenuSelectedFcn = createCallbackFcn(app, @longerichMenuSelected, true);
            app.longerich.Text = 'Longerich (1996)';
            app.report_write = uimenu(app.report);
            app.report_write.MenuSelectedFcn = createCallbackFcn(app, @report_writeMenuSelected, true);
            app.report_write.Text = 'Create Output Report ';
            app.calibration = uimenu(app.calculationmanagerUIFigure);
            app.calibration.Text = 'Calibration';
            app.calibration_show = uimenu(app.calibration);
            app.calibration_show.MenuSelectedFcn = createCallbackFcn(app, @calibration_showMenuSelected, true);
            app.calibration_show.Text = 'Show Drift';
            app.GridLayout2 = uigridlayout(app.calculationmanagerUIFigure);
            app.UITable = uitable(app.GridLayout2);
            app.UITable.ColumnName = {'Id'};
            app.UITable.RowName = {};
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.UITable.SelectionChangedFcn = createCallbackFcn(app, @UITableSelectionChanged, true);
            app.UITable.ButtonDownFcn = createCallbackFcn(app, @UITableButtonDown, true);
            app.DevButtonTableUpdate = uibutton(app.GridLayout2, 'push');
            app.DevButtonTableUpdate.ButtonPushedFcn = createCallbackFcn(app, @DevButtonTableUpdatePushed, true);
            app.DevButtonTableUpdate.Text = 'Dev Button (Table Update)';
            app.Panel = uipanel(app.GridLayout2);
            app.GridLayout = uigridlayout(app.Panel);
            app.ApplyToAllButton = uibutton(app.GridLayout, 'push');
            app.ApplyToAllButton.Enable = 'off';
            app.ApplyToAllButton.Text = 'Apply To All';
            app.ElementsButton = uibutton(app.GridLayout, 'push');
            app.ElementsButton.ButtonPushedFcn = createCallbackFcn(app, @ElementsButtonPushed, true);
            app.ElementsButton.Enable = 'off';
            app.ElementsButton.Text = 'Elements';
            app.DefineSampleSettingsButton = uibutton(app.GridLayout, 'push');
            app.DefineSampleSettingsButton.Enable = 'off';
            app.DefineSampleSettingsButton.Text = 'Define Sample Settings';
            app.PlotButton = uibutton(app.GridLayout, 'push');
            app.PlotButton.Enable = 'off';
            app.PlotButton.Text = 'Plot';
            app.CopyButton = uibutton(app.GridLayout, 'push');
            app.CopyButton.Enable = 'off';
            app.CopyButton.Text = 'Copy';
            app.DeleteButton = uibutton(app.GridLayout, 'push');
            app.DeleteButton.Enable = 'off';
            app.DeleteButton.Text = 'Delete';
            app.h_CPSonly = uicheckbox(app.GridLayout);
            app.h_CPSonly.ValueChangedFcn = createCallbackFcn(app, @h_CPSonlyValueChanged, true);
            app.h_CPSonly.Text = 'CPS data only';
            % Show the figure after all components are created
            app.calculationmanagerUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = calculation_manager(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.calculationmanagerUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.calculationmanagerUIFigure)
        end
    end
end