classdef Interval_Module < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        BaseMenu                        matlab.ui.container.Menu
        SaveSessiontoBaseandExitMenu    matlab.ui.container.Menu
        EliminateSpikesGloballyButton   matlab.ui.control.Button
        CommentLabel                    matlab.ui.control.Label
        DeleteMatrixButton              matlab.ui.control.Button
        DeleteBackgroundButton          matlab.ui.control.Button
        SetMatrixButton                 matlab.ui.control.Button
        SetBackgroundButton             matlab.ui.control.Button
        ErasematrixintervalsgloballyButton  matlab.ui.control.Button
        ErasebackgroundsgloballyButton  matlab.ui.control.Button
        CommentTextArea                 matlab.ui.control.TextArea
        ShowLegendCheckBox              matlab.ui.control.CheckBox
        ShowIntervalsCheckBox           matlab.ui.control.CheckBox
        ProceedtoClassificationModuleButton  matlab.ui.control.Button
        RefreshButton                   matlab.ui.control.Button
        LogscaleCheckBox                matlab.ui.control.CheckBox
        EliminateSpikesButton           matlab.ui.control.Button
        matrix_upper_indentEditField    matlab.ui.control.NumericEditField
        matrix_lower_indentEditField    matlab.ui.control.NumericEditField
        bg_upper_indentEditField        matlab.ui.control.NumericEditField
        bg_lower_indentEditField        matlab.ui.control.NumericEditField
        MatrixUpperLimitPlusTenButton   matlab.ui.control.Button
        MatrixUpperLimitMinusTenButton  matlab.ui.control.Button
        MatrixUpperLimitPlusOneButton   matlab.ui.control.Button
        MatrixUpperLimitMinusOneButton  matlab.ui.control.Button
        MatrixLowerLimitPlusTenButton   matlab.ui.control.Button
        MatrixLowerLimitMinusTenButton  matlab.ui.control.Button
        MatrixLowerLimitPlusOneButton   matlab.ui.control.Button
        MatrixLowerLimitMinusOneButton  matlab.ui.control.Button
        GlobalUpperLimitLabel           matlab.ui.control.Label
        GlobalLowerLimitLabel           matlab.ui.control.Label
        BgUpperLimitPlusTenButton       matlab.ui.control.Button
        BgUpperLimitMinusTenButton      matlab.ui.control.Button
        BgUpperLimitPlusOneButton       matlab.ui.control.Button
        BgUpperLimitMinusOneButton      matlab.ui.control.Button
        BgLowerLimitPlusTenButton       matlab.ui.control.Button
        BgLowerLimitMinusTenButton      matlab.ui.control.Button
        BgLowerLimitPlusOneButton       matlab.ui.control.Button
        BgLowerLimitMinusOneButton      matlab.ui.control.Button
        QuantificationButton            matlab.ui.control.Button
        DriftCorrectionButton           matlab.ui.control.Button
        ClassificationButton            matlab.ui.control.Button
        SetmatrixintervalsautomaticallyButton  matlab.ui.control.Button
        SetbackgroundintervalsautomaticallyButton  matlab.ui.control.Button
        ZoomintoSignalCheckBox          matlab.ui.control.CheckBox
        PlotBackgroundcorrectedCheckBox  matlab.ui.control.CheckBox
        PlotstylesListBox               matlab.ui.control.ListBox
        PreviousSignalButton            matlab.ui.control.Button
        SpotListBox                     matlab.ui.control.ListBox
        NextSignalButton                matlab.ui.control.Button
        ElementLegendListBox            matlab.ui.control.ListBox
        IntervalButton                  matlab.ui.control.Button
        ImportButton                    matlab.ui.control.Button
        SpotAxes                        matlab.ui.control.UIAxes
    end


    properties (Access = public)
        base
        project_id
        session_id
    end

    properties (Access = private)
        sourceapp
        active_spot
        active_spot_indices
        selected_analytes
        selected_spot
        signal_start_idx
        signal_end_idx

        % variables for the moving interval lines
        hLineToDrag
        hVerticalLines
        hVerticalLinesBounds

        % variables for the plotstyles
        selected_plotstyle
        active_ratios

        % for setting the intervals manually
        selected_interval_type

    end


    methods (Access = private)

        function DisplayErrorNG(app,ME,WhichOperation)
            disp(' ')
            disp('%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%')
            disp('%%%%%%%%%%%%%% Error %%%%%%%%%%%%%%%%')
            disp('%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%')
            disp(['Error occured during: ' WhichOperation])
            % for n=1
            for n=numel(ME.stack):-1:1
                disp('Error in file:')
                disp(ME.stack(n).file)
                disp(['Error in line: ',num2str(ME.stack(n).line)])
            end
            disp(['Error in messsage: ' ME.message])
            disp(' ')
            disp('%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%')
            disp(' ')

        end

        function update_Interval_Module_GUI(app)
            % here populate the spotname listbox
            app.SpotListBox.Items = app.base.project.session.spotname;
            app.SpotListBox.ItemsData = 1:numel(app.base.project.session.spotname);

            % populate the plotstyle listbox
            app.PlotstylesListBox.Items = cellstr(fieldnames(app.base.project.session.plotstyles));
            app.PlotstylesListBox.ItemsData = 1:numel(app.PlotstylesListBox.Items);

            % update the comment box
            % show the correct comment in the textbox depending on
            % app.selected_spot as an index
            if isfield(app.base.project.session.spot, 'comment') && ...
                    ~isempty(app.base.project.session.spot.comment) && ...
                    app.selected_spot <= numel(app.base.project.session.spot.comment)
                current_comment = app.base.project.session.spot.comment{app.selected_spot};
                if ~isempty(current_comment)
                    app.CommentTextArea.Value = current_comment;
                else
                    app.CommentTextArea.Value = '';
                end
            else
                app.CommentTextArea.Value = '';
            end

            % do updates in the right order!
            update_active_spot(app)
            update_elementlistbox_with_plotstyle_analytes(app)
            update_spot_plot(app)

            if app.ShowIntervalsCheckBox.Value
                update_interval_lines(app, app.SpotAxes, true)
            end

        end

        %%% functions to get the data to plot
        function update_active_spot(app)
            % so that something is plotted when the Module is initialized
            if isempty(app.selected_spot)
                app.selected_spot = 1;
            end

            signal_start = app.base.project.session.spot.signal_starts(app.selected_spot);
            signal_end = app.base.project.session.spot.signal_ends(app.selected_spot);

            if isfield(app.base.project.session, 'ms_data')

                fullsignal = app.base.project.session.ms_data;

                avg_spot_length = mean(diff(app.base.project.session.spot.signal_ends));

                if app.selected_spot == 1
                    % For the first spot, ensure the start index is not negative
                    first_spot_start = max(1, round(signal_end - avg_spot_length));
                    spot_to_plot = fullsignal(first_spot_start:signal_end, :);
                    app.active_spot_indices = first_spot_start:signal_end;
                else
                    % Adjust the indices to correctly capture the spot
                    previous_signal_end = app.base.project.session.spot.signal_ends(app.selected_spot - 1);
                    spot_to_plot = fullsignal(previous_signal_end:signal_end, :);
                    app.active_spot_indices = previous_signal_end:signal_end;
                end

                app.active_spot = spot_to_plot; % spot that is plotted

            elseif isfield(app.base.project.session, 'spot') && isfield(app.base.project.session.spot, 'ms_data_single_spot') && ~isempty(app.base.project.session.spot.ms_data_single_spot)
                
                app.active_spot = app.base.project.session.spot.ms_data_single_spot{app.selected_spot};
                app.active_spot_indices = 1:height(app.base.project.session.spot.ms_data_single_spot{app.selected_spot});
            
            else
                message = "You are generating new problems";
                uialert(app.UIFigure,message,"Warning", ...
                    "Icon","warning");
            end

            % store the signal starts and ends of the active_spot so that
            % the xlims can easily be adjusted for the zoom to signal
            % checkbox
            app.signal_start_idx = signal_start;
            app.signal_end_idx = signal_end;

            % here do the background correction if the checkbox is checked
            if app.PlotBackgroundcorrectedCheckBox.Value
                background_correction(app) % this changes and mutates the app.active_spot
            end

        end

        function background_correction(app)
            % get all the rows from indices background_start to background_end
            background_start = app.base.project.session.spot.background_starts{app.selected_spot};
            background_end = app.base.project.session.spot.background_ends{app.selected_spot};

            % case of full measurement input
            if isfield(app.base.project.session, 'ms_data')

                signal = app.base.project.session.ms_data;

                % case of single_spot input
            elseif isfield(app.base.project.session, 'spot') && isfield(app.base.project.session.spot, 'ms_data_single_spot') && ~isempty(app.base.project.session.spot.ms_data_single_spot)

                signal = app.active_spot;

            end

            % get all the columns of the signal table except the first one
            background_values = signal(background_start:background_end, 2:end);

            % now calculate the mean of every column and store it in background_values_mean
            background_values_mean = mean(background_values, 1);

            % then subtract these mean values from every row in the corresponding columns in the app.active_spot
            app.active_spot(:, 2:end) = app.active_spot(:, 2:end) - background_values_mean;

            % Replace negative values with 1
            app.active_spot{:, 2:end} = max(app.active_spot{:, 2:end}, 0);

        end

        %%%%%%%%% Plot functions %%%%%%%%%%%%%

        function get_plotstyles(app)
            % Define the folder path
            folderPath = "D:\OneDrive - Universitaet Bern\PhD\Project LARP\Extras\Signal Plot Settings";
            csvFiles = dir(fullfile(folderPath, '*.csv'));

            % Initialize plotstyles with Default field so that there is
            % something to plot at startup
            %app.base.project.session.plotstyles.Default.Analytes = app.base.project.session.analytes;
            analytes = app.base.project.session.analytes;
            numAnalytes = length(analytes);
            app.base.project.session.plotstyles.Default.Analytes = analytes;
            % Set default values
            % Generate a color scheme using MATLAB's default color order
            colors = lines(numAnalytes);
            % Set default values
            app.base.project.session.plotstyles.Default.Color = arrayfun(@(i) colors(i, :), 1:numAnalytes, 'UniformOutput', false);
            app.base.project.session.plotstyles.Default.LineWidth = repmat(1.5, numAnalytes, 1);
            app.base.project.session.plotstyles.Default.Linestyle = repmat({'-'}, numAnalytes, 1); % Solid line
            app.base.project.session.plotstyles.Default.Marker = repmat({'none'}, numAnalytes, 1);
            app.base.project.session.plotstyles.Default.MarkerSize = repmat({'none'}, numAnalytes, 1);
            app.base.project.session.plotstyles.Default.MarkerFaceColor = repmat({'none'}, numAnalytes, 1);
            app.base.project.session.plotstyles.Default.MarkerEdgeColor = repmat({'none'}, numAnalytes, 1);


            % Loop through each CSV file in the folder
            for k = 1:length(csvFiles)
                baseFileName = csvFiles(k).name;
                if strcmp(baseFileName, 'Template.csv')
                    continue; % Skip the Template.csv file
                end
                fullFileName = fullfile(folderPath, baseFileName);
                try
                    data = readtable(fullFileName);

                    data = data(~any(ismissing(data), 2), :);

                    dataStruct = table2struct(data, 'ToScalar', true);
                    [~, name, ~] = fileparts(baseFileName);

                    app.base.project.session.plotstyles.(name) = dataStruct;

                catch ME
                    warning('Failed to read or process file: %s\nError: %s', fullFileName, ME.message);
                end
            end

            % Create a table for Template.csv with empty columns except Analytes
            defaultTable = table(analytes, repmat({''}, numAnalytes, 1), repmat({''}, numAnalytes, 1), ...
                repmat({''}, numAnalytes, 1), repmat({''}, numAnalytes, 1), ...
                repmat({''}, numAnalytes, 1), repmat({''}, numAnalytes, 1), ...
                repmat({''}, numAnalytes, 1), 'VariableNames', ...
                {'Analytes', 'Color', 'LineWidth', 'Linestyle', 'Marker', 'MarkerSize', 'MarkerFaceColor', 'MarkerEdgeColor'});

            % Write the table to Template.csv
            defaultFileName = fullfile(folderPath, 'Template.csv');
            writetable(defaultTable, defaultFileName);

        end

        function update_elementlistbox_with_plotstyle_analytes(app)

            if isempty(app.selected_plotstyle)
                app.selected_plotstyle = 1; % this sets the plotstyle to default at startup
            end

            % get the selected plotstyle
            fields = fieldnames(app.base.project.session.plotstyles);
            chosenfield = fields{app.selected_plotstyle};
            selectedPlotStyle = app.base.project.session.plotstyles.(chosenfield);
            %% here there needs to come a if statement, either calculate ratios if isfields nominator, denominator or just proceed as normal
            if isfield(selectedPlotStyle, 'Analytes')
                try
                    % Update the ElementLegendListBox with the analytes from the selected plot style
                    analytes = selectedPlotStyle.Analytes;
                    analyteIndices = zeros(1, numel(analytes));

                    [isMember, analyteIndices] = ismember(analytes, app.active_spot.Properties.VariableNames);

                    % Filter out analytes that are not members
                    validAnalytes = analytes(isMember);
                    validIndices = analyteIndices(isMember);

                    % Adjust validIndices to exclude the "Time" column
                    validIndices = validIndices - 1;

                    % Update the ElementLegendListBox Items and ItemsData
                    app.ElementLegendListBox.Items = validAnalytes;
                    app.ElementLegendListBox.ItemsData = validIndices;

                catch ME

                    message = "Custom plotstyle failed - check .csv files for wrong or missing entries";
                    uialert(app.UIFigure,message,"Warning", ...
                        "Icon","warning");
                end


            elseif isfield(selectedPlotStyle, 'Nominator') && isfield(selectedPlotStyle, 'Denominator')
                try
                    % Extract the relevant columns from app.active_spot
                    nominatorCols = selectedPlotStyle.Nominator;
                    denominatorCols = selectedPlotStyle.Denominator;

                    % Initialize the table for ratios
                    ratios = table();

                    % Calculate the ratios row-wise
                    for i = 1:length(nominatorCols)
                        nominator = app.active_spot.(nominatorCols{i});
                        denominator = app.active_spot.(denominatorCols{i});
                        ratioName = [nominatorCols{i} '/' denominatorCols{i}];
                        ratios.(ratioName) = nominator ./ denominator;
                    end

                    % Save the ratios table in app
                    app.active_ratios = ratios;

                    app.ElementLegendListBox.Items = app.active_ratios.Properties.VariableNames;
                    app.ElementLegendListBox.ItemsData = 1:numel(app.ElementLegendListBox.Items);
                    % output of this elseif block need to be the ElementListBox
                    % items and data
                catch ME

                    message = "Calculation of some ratios failed - check .csv files for wrong or missing entries";
                    uialert(app.UIFigure,message,"Warning", ...
                        "Icon","warning");
                end

            end

        end

        function update_spot_plot(app)
            cla(app.SpotAxes)

            %% plot the main plot
            % Att this x_default has to change if single signals are input
            x_default = app.active_spot_indices';

            %% Retrieve the selected plot style
            fields = fieldnames(app.base.project.session.plotstyles);
            chosenfield = fields{app.selected_plotstyle};
            selectedPlotStyle = app.base.project.session.plotstyles.(chosenfield);

            if chosenfield == "Default"
                %% this works with y_default
                if isempty(app.selected_analytes)
                    % this +1 has to be there to account for the shift due to the time column
                    y = table2array(app.active_spot(:, app.ElementLegendListBox.ItemsData + 1)); %
                    analytes_to_plot = app.ElementLegendListBox.Items;
                else % if there are selected_anlaytes in the ElementLegendListBox
                    y = table2array(app.active_spot(:, app.selected_analytes + 1)); %
                    analytes_to_plot = app.ElementLegendListBox.Items(app.selected_analytes);
                end

                % x and y data is plotted
                hold(app.SpotAxes, 'on');
                for i = 1:numel(analytes_to_plot)
                    analyte = analytes_to_plot{i};
                    idx = find(strcmp(selectedPlotStyle.Analytes, analyte));

                    if ~isempty(idx)
                        if strcmp(selectedPlotStyle.Marker{idx}, 'none')
                            plot(app.SpotAxes, x_default, y(:, i), ...
                                'Color', selectedPlotStyle.Color{idx}, ...
                                'LineWidth', selectedPlotStyle.LineWidth(idx), ...
                                'LineStyle', selectedPlotStyle.Linestyle{idx});
                        else
                            plot(app.SpotAxes, x_default, y(:, i), ...
                                'Color', selectedPlotStyle.Color{idx}, ...
                                'LineWidth', selectedPlotStyle.LineWidth(idx), ...
                                'LineStyle', selectedPlotStyle.Linestyle{idx}, ...
                                'Marker', selectedPlotStyle.Marker{idx}, ...
                                'MarkerSize', selectedPlotStyle.MarkerSize(idx), ...
                                'MarkerFaceColor', selectedPlotStyle.MarkerFaceColor{idx}, ...
                                'MarkerEdgeColor', selectedPlotStyle.MarkerEdgeColor{idx});
                        end
                    end
                end
                hold(app.SpotAxes, 'off');

            %% plot the custom files
            elseif isfield(selectedPlotStyle, 'Analytes')

                if isempty(app.selected_analytes)
                    % this +1 has to be there to account for the shift due to the time column
                    y = table2array(app.active_spot(:, app.ElementLegendListBox.ItemsData + 1)); %this plots the actual data
                    analytes_to_plot = app.ElementLegendListBox.Items;

                else % if there are selected_anlaytes in the ElementLegendListBox
                    y = table2array(app.active_spot(:, app.selected_analytes + 1)); %
                    analytes_to_plot = app.active_spot(:, app.selected_analytes + 1).Properties.VariableNames;

                end

                % x and y data is plotted
                hold(app.SpotAxes, 'on');
                for i = 1:numel(analytes_to_plot)
                    analyte = analytes_to_plot{i};
                    idx = find(strcmp(selectedPlotStyle.Analytes, analyte));

                    if ~isempty(idx)
                        if strcmp(selectedPlotStyle.Marker{idx}, 'none')
                            plot(app.SpotAxes, x_default, y(:, i), ...
                                'Color', selectedPlotStyle.Color{idx}, ...
                                'LineWidth', selectedPlotStyle.LineWidth(idx), ...
                                'LineStyle', selectedPlotStyle.Linestyle{idx});

                        else
                            plot(app.SpotAxes, x_default, y(:, i), ...
                                'Color', selectedPlotStyle.Color{idx}, ...
                                'LineWidth', selectedPlotStyle.LineWidth(idx), ...
                                'LineStyle', selectedPlotStyle.Linestyle{idx}, ...
                                'Marker', selectedPlotStyle.Marker{idx}, ...
                                'MarkerSize', selectedPlotStyle.MarkerSize(idx), ...
                                'MarkerFaceColor', selectedPlotStyle.MarkerFaceColor{idx}, ...
                                'MarkerEdgeColor', selectedPlotStyle.MarkerEdgeColor{idx});
                        end
                    end
                end
                hold(app.SpotAxes, 'off');

                %% plot the ratios
            elseif isfield(selectedPlotStyle, 'Nominator') && isfield(selectedPlotStyle, 'Denominator')

                if isempty(app.selected_analytes)
                    y = table2array(app.active_ratios(:, app.ElementLegendListBox.ItemsData)); %
                    analytes_to_plot = app.ElementLegendListBox.Items;
                else 
                    y = table2array(app.active_ratios(:, app.selected_analytes)); %
                    analytes_to_plot = app.ElementLegendListBox.Items(app.selected_analytes);
                end


                % x and y data is plotted
                hold(app.SpotAxes, 'on');
                for i = 1:numel(analytes_to_plot)
                    analyte = analytes_to_plot{i};
                    ratioNames = strcat(selectedPlotStyle.Nominator, '/', selectedPlotStyle.Denominator);
                    idx = find(strcmp(ratioNames, analyte));

                    if ~isempty(idx)
                        if strcmp(selectedPlotStyle.Marker{idx}, 'none')
                            plot(app.SpotAxes, x_default, y(:, i), ...
                                'Color', selectedPlotStyle.Color{idx}, ...
                                'LineWidth', selectedPlotStyle.LineWidth(idx), ...
                                'LineStyle', selectedPlotStyle.Linestyle{idx});

                        else
                            plot(app.SpotAxes, x_default, y(:, i), ...
                                'Color', selectedPlotStyle.Color{idx}, ...
                                'LineWidth', selectedPlotStyle.LineWidth(idx), ...
                                'LineStyle', selectedPlotStyle.Linestyle{idx}, ...
                                'Marker', selectedPlotStyle.Marker{idx}, ...
                                'MarkerSize', selectedPlotStyle.MarkerSize(idx), ...
                                'MarkerFaceColor', selectedPlotStyle.MarkerFaceColor{idx}, ...
                                'MarkerEdgeColor', selectedPlotStyle.MarkerEdgeColor{idx});
                        end
                    end
                end
                hold(app.SpotAxes, 'off');
            end

            % Set the y-axis to logarithmic scale
            % similar to this I can check for the plotstyles and set things
            if app.LogscaleCheckBox.Value
                set(app.SpotAxes, 'YScale', 'log');
            else
                set(app.SpotAxes, 'YScale', 'linear');
            end

            % Adjust x-axis limits if zoom is enabled
            if app.ZoomintoSignalCheckBox.Value
                xlim(app.SpotAxes, [app.signal_start_idx, app.signal_end_idx]);
            else
                xlim(app.SpotAxes, [min(x_default), max(x_default)]);
            end

            ylim(app.SpotAxes, 'auto')
            %app.SpotAxes.YMinorGrid = 'on';
            %app.SpotAxes.YMinorGridLineStyle = '-';
            %

            set(app.SpotAxes, 'yminorgrid', 'on')
            set(app.SpotAxes, 'MinorGridLIneStyle', '-')
            set(app.SpotAxes, 'MinorGridAlpha', 0.15)


            % Set the title of the plot
            title(app.SpotAxes, app.base.project.session.spotname(app.selected_spot), 'Interpreter', 'none');

            % Add a legend to the plot in the top left corner with 3 columns
            if app.ShowLegendCheckBox.Value
                legend(app.SpotAxes, analytes_to_plot, 'Location', 'northwest', 'NumColumns', 3, 'AutoUpdate', 'off');
            end

        end

        %%%%%%% update the movable interval lines and interval areas

        function KeyPressFcn(app, event)
            % Create keyboard callback
            if strcmp(event.Key, 'd')
                SetBackgroundButtonPushed(app)
            elseif strcmp(event.Key, 'f')
                SetMatrixButtonPushed(app)
            end
        end

        function draw_interval_via_rectangles(app, type)

            ROI_sampling = drawrectangle(app.SpotAxes,'Color',[0.8 0.8 0.8],'Rotatable',0,'InteractionsAllowed','all', 'LineWidth', 0.1);

            xrange=[ROI_sampling.Position(1) ROI_sampling.Position(1)+ROI_sampling.Position(3)];
            
            if strcmp(type,"Background")

                background_start = ceil(xrange(1));
                background_end = ceil(xrange(2));
                app.base.project.session.spot.background_starts{app.selected_spot} = background_start;
                app.base.project.session.spot.background_ends{app.selected_spot} = background_end;

            elseif strcmp(type,"Matrix")

                matrix_start = ceil(xrange(1));
                matrix_end = ceil(xrange(2));
                app.base.project.session.spot.matrix_starts{app.selected_spot} = matrix_start;
                app.base.project.session.spot.matrix_ends{app.selected_spot} = matrix_end;

            end

            delete(ROI_sampling)

        end

        function update_interval_lines(app, ax2plot, update_lines)
            if not(exist('ax2plot','var'))
                ax2plot = app.SpotAxes;
            end

            if not(exist('update_lines','var'))
                update_lines = false;
            end


            % Check and plot the background intervals if not empty
            if isfield(app.base.project.session.spot, 'background_starts') && isfield(app.base.project.session.spot, 'background_ends')...
                    && ~isempty(app.base.project.session.spot.background_starts{app.selected_spot}) && ~isempty(app.base.project.session.spot.background_ends{app.selected_spot})

                bg_start = app.base.project.session.spot.background_starts{app.selected_spot};
                bg_end = app.base.project.session.spot.background_ends{app.selected_spot};

                if update_lines == true
                    app.hVerticalLines(1).xl = xline(ax2plot, bg_start, 'linewidth', 2, 'Color', [0.5 0.5 0.5],'Tag','spot_bgwindow','hittest','off'); % Background interval start
                    app.hVerticalLines(2).xl = xline(ax2plot, bg_end, 'linewidth', 2, 'Color', [0.5 0.5 0.5],'Tag','spot_bgwindow','hittest','off'); % Background interval end
                    update_interval_areas(app,'all')
                end
            end

            %Check and plot the signal intervals if not empty
            if isfield(app.base.project.session.spot, 'matrix_starts') && isfield(app.base.project.session.spot, 'matrix_ends')...
                    && ~isempty(app.base.project.session.spot.matrix_starts{app.selected_spot}) && ~isempty(app.base.project.session.spot.matrix_ends{app.selected_spot})

                sig_start = app.base.project.session.spot.matrix_starts{app.selected_spot};
                sig_end = app.base.project.session.spot.matrix_ends{app.selected_spot};

                if update_lines==true
                    app.hVerticalLines(3).xl =    xline(ax2plot, sig_start, 'linewidth', 2, 'Color', "#77AC30",'Tag','spot_matwindow','hittest','off'); % Signal interval start
                    app.hVerticalLines(4).xl =  xline(ax2plot, sig_end, 'linewidth', 2, 'Color', "#77AC30",'Tag','spot_matwindow','hittest','off'); % Signal interval end
                    update_interval_areas(app,'all')
                end
            end

            
        end

        function update_interval_areas(app, which)

            if strcmp(which,'all')
                tmp=findobj(app.SpotAxes,'Type','Patch');
                delete(tmp);
                n_k=1:2:numel(app.hVerticalLines);
            % elseif strcmp(which,'background')
            %     tmp=findobj(app.SpotAxes,'Type','Patch');
            %     delete(tmp);
            % 
            %     n_k=[1,2];
            % elseif strcmp(which,'matrix')
            %     tmp=findobj(app.SpotAxes,'Type','Patch');
            %     delete(tmp);
            %     n_k=[3,4];
            %     %n_k=5:2:numel(app.hVerticalLines);

            end

            for k = n_k
                try
                    if numel(app.hVerticalLines)>k &&  not(isempty(app.hVerticalLines(k).xl)) && not(isempty(app.hVerticalLines(k+1).xl)) && isvalid(app.hVerticalLines(k).xl) && isvalid(app.hVerticalLines(k+1).xl)
                        ax2plot = app.SpotAxes;
                        ylimits = ylim(ax2plot);
                        x = sort([app.hVerticalLines(k).xl.Value app.hVerticalLines(k+1).xl.Value]);
                        lowerBoundary(1:2) = min([ylimits ylimits]);
                        upperBoundary(1:2) = max([ylimits ylimits]);
                        patch(ax2plot,[x fliplr(x)], [upperBoundary  fliplr(lowerBoundary)], app.hVerticalLines(k+1).xl.Color,'FaceAlpha',0.1,'hittest','off');
                        ylim(ax2plot,ylimits);
                    end
                catch ME
                    ME;
                end
            end
        end
        
        %%%%%%%%%%%%% spike elimination %%%%%%%%%%%%%

        function [corrected_data] = spike_elimination(app, data)

            % Define Constants
            cpvalueseven = 2.097; % For N=7 measurements, significance level 1%
            cpvaluenine = 2.323;  % For N=9 measurements, significance level 1%
            %Lower threshold for spike identification, prevents identification of a
            %spike each time for sporadically occurring elements
            threshold = 600;     % Minimum threshold for spike detection [cps]

            % Apply grubbs test correction using moving window
            corrected_data = data;
            for col = 1:size(data,2)
                tstep = 5;
                while tstep < size(data,1)-4
                    % Only check points above threshold
                    if data(tstep,col) > threshold
                        % Get window values
                        csp = data(tstep,col);
                        onebefore = data(tstep-1,col);
                        oneafter = data(tstep+1,col);
                        twobefore = data(tstep-2,col);
                        twoafter = data(tstep+2,col);
                        threebefore = data(tstep-3,col);
                        threeafter = data(tstep+3,col);
                        fourafter = data(tstep+4,col);
                        fourbefore = data(tstep-4,col);

                        % Calculate statistics for both windows
                        window7 = [onebefore, oneafter, twoafter, twobefore, threebefore, threeafter, csp];
                        window9 = [window7, fourafter, fourbefore];

                        stdseven = std(window7);
                        meanseven = mean(window7);
                        stdnine = std(window9);
                        meannine = mean(window9);

                        % Check if point is outlier using both criteria
                        if ((abs(csp - meanseven))/stdseven) > cpvalueseven && ...
                                ((abs(csp - meannine))/stdnine) > cpvaluenine

                            % Replace with average of surrounding points (excluding current point)
                            surrounding = [onebefore, oneafter, twoafter, twobefore, threebefore, threeafter];
                            corrected_data(tstep,col) = mean(surrounding);
                        end
                    end
                    tstep = tstep + 1;
                end
            end

        end

    end


    methods (Access = public)

        %%%%%%%% API functions %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        function set_automatic_background(app, settings)

            try
                test_state = true; % is needed later as a failsafe so that no broken data gets into the data structure

                % if settings is not sent or something wrong is put in
                if not(exist('settings', 'var')) || not(isstruct(settings))
                    settings = struct();
                end

                % if the lower_indent is not input, make it some value
                if not(isfield(settings,'bg_lower_indent')) || isempty(settings.bg_lower_indent)
                    settings.bg_lower_indent = app.bg_lower_indentEditField.Value; % default value
                end

                % if the lower_indent is not input, make it some value
                if not(isfield(settings,'bg_upper_indent')) || isempty(settings.bg_upper_indent)
                    settings.bg_upper_indent = app.bg_upper_indentEditField.Value; % default value
                end

                % Ensure the value is not lower than 0
                if settings.bg_lower_indent < 0
                    settings.bg_lower_indent = 0;
                elseif settings.bg_upper_indent < 0
                    settings.bg_upper_indent = 0;
                end

                lower_indent = settings.bg_lower_indent;
                upper_indent = settings.bg_upper_indent;

                signal_start = app.base.project.session.spot.signal_starts;
                signal_end = app.base.project.session.spot.signal_ends;

                % Initialize arrays to hold background start and end indices
                background_starts = zeros(size(signal_start))';
                background_ends =  zeros(size(signal_start))';

                % case of full measurement input
                if isfield(app.base.project.session, 'ms_data')

                    % Handle the first spot separately
                    avg_spot_length = mean(diff(signal_end));
                    first_spot_start = max(1, round(signal_end(1) - avg_spot_length));

                    background_starts(1) = first_spot_start + lower_indent;
                    background_ends(1) = signal_start(1) - upper_indent;

                    % Loop through the remaining spots
                    for i = 2:numel(signal_start)

                        background_starts(i) = signal_end(i-1) + lower_indent;
                        background_ends(i) = signal_start(i) - upper_indent;

                    end

                    % case of single_spot input
                elseif isfield(app.base.project.session, 'spot') && isfield(app.base.project.session.spot, 'ms_data_single_spot') && ~isempty(app.base.project.session.spot.ms_data_single_spot)

                    % Loop through the remaining spots
                    for i = 1:numel(signal_start)

                        background_starts(i) = 1 + lower_indent;
                        background_ends(i) = signal_start(i) - upper_indent;

                    end

                end

                % if test_state = false, push the data from the cache to the
                % data structure
                if test_state == false
                    return
                else
                    % Store the results in the app structure
                    app.base.project.session.spot.background_starts = num2cell(background_starts');
                    app.base.project.session.spot.background_ends = num2cell(background_ends');
                end

                if isfield(settings, 'interval_module_GUI_update_state') && settings.interval_module_GUI_update_state == true
                    update_Interval_Module_GUI(app)
                end

            catch ME
                DisplayErrorNG(app,ME,'set_automatic_background')

                message = "set_automatic_background failed";
                uialert(app.UIFigure,message,"Warning", ...
                    "Icon","warning");
                disp("set_automatic_background failed")
            end

        end

        function set_automatic_matrix(app, settings)

            try
                test_state = true; % is needed later as a failsafe so that no broken data gets into the data structure

                % if settings is not sent or something wrong is put in
                if not(exist('settings', 'var')) || not(isstruct(settings))
                    settings = struct();
                end

                % if the lower_indent is not input, make it some value
                if not(isfield(settings,'matrix_lower_indent')) || isempty(settings.matrix_lower_indent)
                    settings.matrix_lower_indent = app.matrix_lower_indentEditField.Value; % default value
                end

                % if the lower_indent is not input, make it some value
                if not(isfield(settings,'matrix_upper_indent')) || isempty(settings.matrix_upper_indent)
                    settings.matrix_upper_indent = app.matrix_upper_indentEditField.Value; % default value
                end

                lower_indent = settings.matrix_lower_indent;
                upper_indent = settings.matrix_upper_indent;

                signal_starts = app.base.project.session.spot.signal_starts;
                signal_ends = app.base.project.session.spot.signal_ends;

                % Initialize arrays to hold background start and end indices
                matrix_starts = zeros(size(signal_starts))';
                matrix_ends =  zeros(size(signal_ends))';

                % Loop through the remaining spots
                for i = 1:numel(matrix_starts)

                    matrix_starts(i) = signal_starts(i) + lower_indent;
                    matrix_ends(i) = signal_ends(i) - upper_indent;

                end

                % if test_state = false, push the data from the cache to the
                % data structure
                if test_state == false
                    return
                else
                    % Store the results in the app structure
                    app.base.project.session.spot.matrix_starts = num2cell(matrix_starts');
                    app.base.project.session.spot.matrix_ends = num2cell(matrix_ends');
                end

                if isfield(settings, 'interval_module_GUI_update_state') && settings.interval_module_GUI_update_state == true
                    update_Interval_Module_GUI(app)
                end

            catch ME
                DisplayErrorNG(app,ME,'set_automatic_matrix')

                message = "set_automatic_matrix failed";
                uialert(app.UIFigure,message,"Warning", ...
                    "Icon","warning");
                disp("set_automatic_matrix failed")
            end

        end

        function spike_elimination_public(app, settings)

            try
                test_state = true; % is needed later as a failsafe so that no broken data gets into the data structure

                % if settings is not sent or something wrong is put in
                if not(exist('settings', 'var')) || not(isstruct(settings))
                    settings = struct();
                end

                % if the spike_elimination is not input, make it some false
                if not(isfield(settings,'spike_elimination')) || isempty(settings.spike_elimination)
                    settings.spike_elimination = false; % default value
                end

                % if the spike_elimination is not input, make it some false
                if not(isfield(settings,'spike_elimination_spotnumber')) || isempty(settings.spike_elimination_spotnumber)
                    settings.spike_elimination_spotnumber = []; % default value
                end

                % if test_state = false, push the data from the cache to the
                % data structure
                if test_state == false
                    return
                else

                    % if no spotnumbers are input the spike elimination is
                    % done globally
                    if settings.spike_elimination == true && not(isfield(settings,'spike_elimination_spotnumber')) || isempty(settings.spike_elimination_spotnumber)

                        if isfield(app.base.project.session, 'ms_data')

                            data = table2array(app.base.project.session.ms_data(:, 2:end));
                            [corrected_data] = spike_elimination(app, data);
                            app.base.project.session.ms_data(:, 2:end) = array2table(corrected_data);

                        elseif isfield(app.base.project.session.spot, 'ms_data_single_spot')

                            % Iterate through all spots
                            for spot_idx = 1:length(app.base.project.session.spot.ms_data_single_spot)
                                data = table2array(app.base.project.session.spot.ms_data_single_spot{spot_idx}(:, 2:end));
                                [corrected_data] = spike_elimination(app, data);
                                app.base.project.session.spot.ms_data_single_spot{spot_idx}(:, 2:end) = array2table(corrected_data);
                            end

                        end

                        % if spike_elimation_spotnumbers are input it only
                        % does those
                    elseif settings.spike_elimination == true && isfield(settings,'spike_elimination_spotnumber') || ~isempty(settings.spike_elimination_spotnumber)

                        if isfield(app.base.project.session, 'ms_data')

                            signal_ends = app.base.project.session.spot.signal_ends;

                            % Process each requested spot
                            for spot_num = settings.spike_elimination_spotnumber
                                if spot_num == 1
                                    % For first spot, calculate start similarly to update_active_spot
                                    avg_spot_length = mean(diff(signal_ends));
                                    start_idx = max(1, round(signal_ends(1) - avg_spot_length));
                                    end_idx = signal_ends(1);
                                else
                                    start_idx = signal_ends(spot_num - 1);
                                    end_idx = signal_ends(spot_num);
                                end

                                % Apply spike elimination to the specific range
                                data = table2array(app.base.project.session.ms_data(start_idx:end_idx, 2:end));
                                [corrected_data] = spike_elimination(app, data);
                                app.base.project.session.ms_data(start_idx:end_idx, 2:end) = array2table(corrected_data);
                            end

                        elseif isfield(app.base.project.session.spot, 'ms_data_single_spot')

                            % Process each spot number individually
                            for spot_idx = settings.spike_elimination_spotnumber
                                data = table2array(app.base.project.session.spot.ms_data_single_spot{spot_idx}(:, 2:end));
                                [corrected_data] = spike_elimination(app, data);
                                app.base.project.session.spot.ms_data_single_spot{spot_idx}(:, 2:end) = array2table(corrected_data);
                            end

                        end

                    end

                end

                if isfield(settings, 'interval_module_GUI_update_state') && settings.interval_module_GUI_update_state == true
                    update_Interval_Module_GUI(app)
                end

            catch ME
                DisplayErrorNG(app,ME,'spike_elimination_public')

                message = "spike_elimination_public failed";
                uialert(app.UIFigure,message,"Warning", ...
                    "Icon","warning");
                disp("spike_elimination_public failed")
            end


        end


    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, sourceapp, project_name, project_id, session_id, data)
            % coming from the base Att not thought through yet
            % Interval_Module(app, app.base(project_id).project_name, project_id, session_id, app.base(project_id).project(session_id))
            % coming from the Import_Module
            %Interval_Module(app.sourceapp, app.base.project_name, app.project_id, app.session_id, app.base.project)

            if exist("sourceapp","var")
                app.sourceapp = sourceapp;
            end% input argument of startupfcn

            if exist("project_name", "var")
                app.base.project_name = project_name;
            end

            %% when the show session button is pressed & when the Interval_Module is called from another Module
            if  exist("project_id","var") && exist("session_id","var") && exist("data", "var")
                % load the sessin_id and the data
                app.project_id = project_id;
                app.session_id = session_id;
                app.base.project = data;

                app.UIFigure.Name = string(app.base.project.session.session_name);

            end

            app.UIFigure.KeyPressFcn = @(~,event)KeyPressFcn(app,event);

            % initialize the storage arrays for the matrix and the
            % backgrounds
            app.base.project.session.spot.background_starts = arrayfun(@(x) [], ones(size(app.base.project.session.spot.signal_starts)), 'UniformOutput', false);
            app.base.project.session.spot.background_ends = arrayfun(@(x) [], ones(size(app.base.project.session.spot.signal_ends)), 'UniformOutput', false);
            app.base.project.session.spot.matrix_starts = arrayfun(@(x) [], ones(size(app.base.project.session.spot.signal_starts)), 'UniformOutput', false);
            app.base.project.session.spot.matrix_ends = arrayfun(@(x) [], ones(size(app.base.project.session.spot.signal_ends)), 'UniformOutput', false);

            % read in the Plotstyles
            get_plotstyles(app)
  
            %update GUI
            update_Interval_Module_GUI(app)

        end

        % Value changed function: ElementLegendListBox
        function ElementLegendListBoxValueChanged(app, event)
            app.selected_analytes = app.ElementLegendListBox.Value;

            update_Interval_Module_GUI(app)
        end

        % Value changed function: SpotListBox
        function SpotListBoxValueChanged(app, event)
            app.selected_spot = app.SpotListBox.Value;

            update_Interval_Module_GUI(app)

        end

        % Value changed function: ZoomintoSignalCheckBox
        function ZoomintoSignalCheckBoxValueChanged(app, event)
            update_Interval_Module_GUI(app)

        end

        % Value changed function: LogscaleCheckBox
        function LogscaleCheckBoxValueChanged(app, event)
            update_Interval_Module_GUI(app)

        end

        % Button pushed function: SetbackgroundintervalsautomaticallyButton
        function SetbackgroundintervalsautomaticallyButtonPushed(app, event)
            settings.interval_module_GUI_update_state = true;

            set_automatic_background(app, settings);

        end

        % Button pushed function: ProceedtoClassificationModuleButton
        function ProceedtoClassificationModuleButtonPushed(app, event)
            % coming from the Interval_Module
            Classification_Module(app.sourceapp, app.base.project_name, app.project_id, app.session_id, app.base.project)
        end

        % Value changed function: bg_lower_indentEditField
        function bg_lower_indentEditFieldValueChanged(app, event)
            settings.bg_lower_indent = app.bg_lower_indentEditField.Value;

            % Ensure the new value does not go below 0
            if app.bg_lower_indentEditField.Value < 0
                app.bg_lower_indentEditField.Value = 0;
            end

            settings.interval_module_GUI_update_state = true;
            set_automatic_background(app, settings)

        end

        % Value changed function: bg_upper_indentEditField
        function bg_upper_indentEditFieldValueChanged(app, event)
            settings.bg_upper_indent = app.bg_upper_indentEditField.Value;

            % Ensure the new value does not go below 0
            if app.bg_upper_indentEditField.Value < 0
                app.bg_upper_indentEditField.Value = 0;
            end

            settings.interval_module_GUI_update_state = true;
            set_automatic_background(app, settings)

        end

        % Value changed function: PlotBackgroundcorrectedCheckBox
        function PlotBackgroundcorrectedCheckBoxValueChanged(app, event)
            if isfield(app.base.project.session.spot, 'background_starts') && isfield(app.base.project.session.spot, 'background_ends')...
                    && ~isempty(app.base.project.session.spot.background_starts{app.selected_spot}) && ~isempty(app.base.project.session.spot.background_ends{app.selected_spot})
                    update_Interval_Module_GUI(app)
            else
                app.PlotBackgroundcorrectedCheckBox.Value = false;

                message = "Set Background first";
                uialert(app.UIFigure,message,"Warning", ...
                    "Icon","warning");
            end
        end

        % Value changed function: matrix_lower_indentEditField
        function matrix_lower_indentEditFieldValueChanged(app, event)
            settings.matrix_lower_indent = app.matrix_lower_indentEditField.Value;

            % Ensure the new value does not go below 0
            if app.matrix_lower_indentEditField.Value < 0
                app.matrix_lower_indentEditField.Value = 0;
            end

            settings.interval_module_GUI_update_state = true;
            set_automatic_matrix(app, settings)

        end

        % Value changed function: matrix_upper_indentEditField
        function matrix_upper_indentEditFieldValueChanged(app, event)
            settings.matrix_upper_indent = app.matrix_upper_indentEditField.Value;

            % Ensure the new value does not go below 0
            if app.matrix_upper_indentEditField.Value < 0
                app.matrix_upper_indentEditField.Value = 0;
            end

            settings.interval_module_GUI_update_state = true;
            set_automatic_matrix(app, settings)
        end

        % Button pushed function: NextSignalButton
        function NextSignalButtonPushed(app, event)
            %check if this is the first selected_spot, if yes and you
            %press it jumps to the first signal, otherwise it just goes
            %one signal forward
            if app.selected_spot == numel(app.SpotListBox.Items)

                app.selected_spot = 1;
                app.SpotListBox.Value = 1;

            else
                app.selected_spot=app.selected_spot + 1;
                app.SpotListBox.Value=app.SpotListBox.Value + 1;
            end

            %update the element list and the plot
            update_Interval_Module_GUI(app)
        end

        % Button pushed function: SetmatrixintervalsautomaticallyButton
        function SetmatrixintervalsautomaticallyButtonPushed(app, event)
            settings.interval_module_GUI_update_state = true;

            set_automatic_matrix(app, settings);

        end

        % Button pushed function: PreviousSignalButton
        function PreviousSignalButtonPushed(app, event)
            %check if this is the first selected_spot, if yes and you
            %press it jumps to the first signal, otherwise it just goes
            %one signal forward
            if app.selected_spot == 1

                app.selected_spot = numel(app.SpotListBox.Items);
                app.SpotListBox.Value = numel(app.SpotListBox.Items);


            else
                app.selected_spot = app.selected_spot - 1;
                app.SpotListBox.Value=app.SpotListBox.Value - 1;
            end

            %update the element list and the plot
            update_Interval_Module_GUI(app)
        end

        % Button pushed function: BgLowerLimitMinusTenButton
        function BgLowerLimitMinusTenButtonPushed(app, event)
            if not(isfield(app.base.project.session.spot, 'background_starts'))
                return
            end

            % Calculate the new value
            app.bg_lower_indentEditField.Value = app.bg_lower_indentEditField.Value - 10;

            % Ensure the new value does not go below 0
            if app.bg_lower_indentEditField.Value < 0
                app.bg_lower_indentEditField.Value = 0;
            end

            settings.bg_lower_indent = app.bg_lower_indentEditField.Value;
            % Ensure the new value does not go below the minimum x limit
            set_automatic_background(app, settings)
            % Update the plot
            update_Interval_Module_GUI(app)
        end

        % Button pushed function: BgLowerLimitMinusOneButton
        function BgLowerLimitMinusOneButtonPushed(app, event)
            if not(isfield(app.base.project.session.spot, 'background_starts'))
                return
            end

            % Calculate the new value
            app.bg_lower_indentEditField.Value = app.bg_lower_indentEditField.Value - 1;

            % Ensure the new value does not go below 0
            if app.bg_lower_indentEditField.Value < 0
                app.bg_lower_indentEditField.Value = 0;
            end

            settings.bg_lower_indent = app.bg_lower_indentEditField.Value;
            % Ensure the new value does not go below the minimum x limit
            set_automatic_background(app, settings)
            % Update the plot
            update_Interval_Module_GUI(app)
        end

        % Button pushed function: BgLowerLimitPlusOneButton
        function BgLowerLimitPlusOneButtonPushed(app, event)
            if not(isfield(app.base.project.session.spot, 'background_starts'))
                return
            end

            % Calculate the new value
            app.bg_lower_indentEditField.Value = app.bg_lower_indentEditField.Value + 1;

            % Ensure the new value does not go below 0
            if app.bg_lower_indentEditField.Value < 0
                app.bg_lower_indentEditField.Value = 0;
            end

            settings.bg_lower_indent = app.bg_lower_indentEditField.Value;
            % Ensure the new value does not go below the minimum x limit
            set_automatic_background(app, settings)
            % Update the plot
            update_Interval_Module_GUI(app)
        end

        % Button pushed function: BgLowerLimitPlusTenButton
        function BgLowerLimitPlusTenButtonPushed(app, event)
            if not(isfield(app.base.project.session.spot, 'background_starts'))
                return
            end

            % Calculate the new value
            app.bg_lower_indentEditField.Value = app.bg_lower_indentEditField.Value + 10;

            % Ensure the new value does not go below 0
            if app.bg_lower_indentEditField.Value < 0
                app.bg_lower_indentEditField.Value = 0;
            end

            settings.bg_lower_indent = app.bg_lower_indentEditField.Value;
            % Ensure the new value does not go below the minimum x limit
            set_automatic_background(app, settings)
            % Update the plot
            update_Interval_Module_GUI(app)
        end

        % Button pushed function: BgUpperLimitMinusTenButton
        function BgUpperLimitMinusTenButtonPushed(app, event)
            if not(isfield(app.base.project.session.spot, 'background_ends'))
                return
            end

            % Calculate the new value
            app.bg_upper_indentEditField.Value = app.bg_upper_indentEditField.Value + 10;

            % Ensure the new value does not go below 0
            if app.bg_upper_indentEditField.Value < 0
                app.bg_upper_indentEditField.Value = 0;
            end

            settings.bg_upper_indent = app.bg_upper_indentEditField.Value;
            % Ensure the new value does not go below the minimum x limit
            set_automatic_background(app, settings)
            % Update the plot
            update_Interval_Module_GUI(app)
        end

        % Button pushed function: BgUpperLimitMinusOneButton
        function BgUpperLimitMinusOneButtonPushed(app, event)
            if not(isfield(app.base.project.session.spot, 'background_ends'))
                return
            end

            % Calculate the new value
            app.bg_upper_indentEditField.Value = app.bg_upper_indentEditField.Value + 1;

            % Ensure the new value does not go below 0
            if app.bg_upper_indentEditField.Value < 0
                app.bg_upper_indentEditField.Value = 0;
            end

            settings.bg_upper_indent = app.bg_upper_indentEditField.Value;
            % Ensure the new value does not go below the minimum x limit
            set_automatic_background(app, settings)
            % Update the plot
            update_Interval_Module_GUI(app)
        end

        % Button pushed function: BgUpperLimitPlusOneButton
        function BgUpperLimitPlusOneButtonPushed(app, event)
            if not(isfield(app.base.project.session.spot, 'background_ends'))
                return
            end

            % Calculate the new value
            app.bg_upper_indentEditField.Value = app.bg_upper_indentEditField.Value - 1;

            % Ensure the new value does not go below 0
            if app.bg_upper_indentEditField.Value < 0
                app.bg_upper_indentEditField.Value = 0;
            end

            settings.bg_upper_indent = app.bg_upper_indentEditField.Value;
            % Ensure the new value does not go below the minimum x limit
            set_automatic_background(app, settings)
            % Update the plot
            update_Interval_Module_GUI(app)
        end

        % Button pushed function: BgUpperLimitPlusTenButton
        function BgUpperLimitPlusTenButtonPushed(app, event)
            if not(isfield(app.base.project.session.spot, 'background_ends'))
                return
            end

            % Calculate the new value
            app.bg_upper_indentEditField.Value = app.bg_upper_indentEditField.Value - 10;

            % Ensure the new value does not go below 0
            if app.bg_upper_indentEditField.Value < 0
                app.bg_upper_indentEditField.Value = 0;
            end

            settings.bg_upper_indent = app.bg_upper_indentEditField.Value;
            % Ensure the new value does not go below the minimum x limit
            set_automatic_background(app, settings)
            % Update the plot
            update_Interval_Module_GUI(app)
        end

        % Button pushed function: MatrixUpperLimitMinusTenButton
        function MatrixUpperLimitMinusTenButtonPushed(app, event)
            if not(isfield(app.base.project.session.spot, 'matrix_ends'))
                return
            end

            % Calculate the new value
            app.matrix_upper_indentEditField.Value = app.matrix_upper_indentEditField.Value + 10;

            % Ensure the new value does not go below 0
            if app.matrix_upper_indentEditField.Value < 0
                app.matrix_upper_indentEditField.Value = 0;
            end

            settings.matrix_upper_indent = app.matrix_upper_indentEditField.Value;
            % Ensure the new value does not go below the minimum x limit
            set_automatic_matrix(app, settings)
            % Update the plot
            update_Interval_Module_GUI(app)
        end

        % Button pushed function: MatrixUpperLimitMinusOneButton
        function MatrixUpperLimitMinusOneButtonPushed(app, event)
            if not(isfield(app.base.project.session.spot, 'matrix_ends'))
                return
            end

            % Calculate the new value
            app.matrix_upper_indentEditField.Value = app.matrix_upper_indentEditField.Value + 1;

            % Ensure the new value does not go below 0
            if app.matrix_upper_indentEditField.Value < 0
                app.matrix_upper_indentEditField.Value = 0;
            end

            settings.matrix_upper_indent = app.matrix_upper_indentEditField.Value;
            % Ensure the new value does not go below the minimum x limit
            set_automatic_matrix(app, settings)
            % Update the plot
            update_Interval_Module_GUI(app)
        end

        % Button pushed function: MatrixUpperLimitPlusOneButton
        function MatrixUpperLimitPlusOneButtonPushed(app, event)
            if not(isfield(app.base.project.session.spot, 'matrix_ends'))
                return
            end

            % Calculate the new value
            app.matrix_upper_indentEditField.Value = app.matrix_upper_indentEditField.Value - 1;

            % Ensure the new value does not go below 0
            if app.matrix_upper_indentEditField.Value < 0
                app.matrix_upper_indentEditField.Value = 0;
            end

            settings.matrix_upper_indent = app.matrix_upper_indentEditField.Value;
            % Ensure the new value does not go below the minimum x limit
            set_automatic_matrix(app, settings)
            % Update the plot
            update_Interval_Module_GUI(app)
        end

        % Button pushed function: MatrixUpperLimitPlusTenButton
        function MatrixUpperLimitPlusTenButtonPushed(app, event)
            if not(isfield(app.base.project.session.spot, 'matrix_ends'))
                return
            end

            % Calculate the new value
            app.matrix_upper_indentEditField.Value = app.matrix_upper_indentEditField.Value - 10;

            % Ensure the new value does not go below 0
            if app.matrix_upper_indentEditField.Value < 0
                app.matrix_upper_indentEditField.Value = 0;
            end

            settings.matrix_upper_indent = app.matrix_upper_indentEditField.Value;
            % Ensure the new value does not go below the minimum x limit
            set_automatic_matrix(app, settings)
            % Update the plot
            update_Interval_Module_GUI(app)
        end

        % Button pushed function: MatrixLowerLimitMinusTenButton
        function MatrixLowerLimitMinusTenButtonPushed(app, event)
            if not(isfield(app.base.project.session.spot, 'matrix_starts'))
                return
            end

            % Calculate the new value
            app.matrix_lower_indentEditField.Value = app.matrix_lower_indentEditField.Value - 10;

            % Ensure the new value does not go below 0
            if app.matrix_lower_indentEditField.Value < 0
                app.matrix_lower_indentEditField.Value = 0;
            end

            settings.matrix_lower_indent = app.matrix_lower_indentEditField.Value;
            % Ensure the new value does not go below the minimum x limit
            set_automatic_matrix(app, settings)
            % Update the plot
            update_Interval_Module_GUI(app)
        end

        % Button pushed function: MatrixLowerLimitMinusOneButton
        function MatrixLowerLimitMinusOneButtonPushed(app, event)
            if not(isfield(app.base.project.session.spot, 'matrix_starts'))
                return
            end

            % Calculate the new value
            app.matrix_lower_indentEditField.Value = app.matrix_lower_indentEditField.Value - 1;

            % Ensure the new value does not go below 0
            if app.matrix_lower_indentEditField.Value < 0
                app.matrix_lower_indentEditField.Value = 0;
            end

            settings.matrix_lower_indent = app.matrix_lower_indentEditField.Value;
            % Ensure the new value does not go below the minimum x limit
            set_automatic_matrix(app, settings)
            % Update the plot
            update_Interval_Module_GUI(app)
        end

        % Button pushed function: MatrixLowerLimitPlusOneButton
        function MatrixLowerLimitPlusOneButtonPushed(app, event)
            if not(isfield(app.base.project.session.spot, 'matrix_starts'))
                return
            end

            % Calculate the new value
            app.matrix_lower_indentEditField.Value = app.matrix_lower_indentEditField.Value + 1;

            % Ensure the new value does not go below 0
            if app.matrix_lower_indentEditField.Value < 0
                app.matrix_lower_indentEditField.Value = 0;
            end

            settings.matrix_lower_indent = app.matrix_lower_indentEditField.Value;
            % Ensure the new value does not go below the minimum x limit
            set_automatic_matrix(app, settings)
            % Update the plot
            update_Interval_Module_GUI(app)
        end

        % Button pushed function: MatrixLowerLimitPlusTenButton
        function MatrixLowerLimitPlusTenButtonPushed(app, event)
            if not(isfield(app.base.project.session.spot, 'matrix_starts'))
                return
            end

            % Calculate the new value
            app.matrix_lower_indentEditField.Value = app.matrix_lower_indentEditField.Value + 10;

            % Ensure the new value does not go below 0
            if app.matrix_lower_indentEditField.Value < 0
                app.matrix_lower_indentEditField.Value = 0;
            end

            settings.matrix_lower_indent = app.matrix_lower_indentEditField.Value;
            % Ensure the new value does not go below the minimum x limit
            set_automatic_matrix(app, settings)
            % Update the plot
            update_Interval_Module_GUI(app)
        end

        % Button down function: SpotAxes
        function SpotAxesButtonDown(app, event)

            if isempty(app.hLineToDrag) || numel(app.hLineToDrag)==0
                %reset_zoom_pan(app)

                for k=1:4
                    if numel(app.hVerticalLines)<k || isempty(app.hVerticalLines(k).xl) || not(isvalid(app.hVerticalLines(k).xl))
                        continue
                    end
                    xVertLineCoord = get(app.hVerticalLines(k).xl,'Value');
                    click_buffer = (max(app.SpotAxes.XLim)-min(app.SpotAxes.XLim))*0.01;
                    xVertLineCoord(1) = xVertLineCoord(1);
                    if abs(event.IntersectionPoint(1) - xVertLineCoord(1)) < click_buffer
                        app.hLineToDrag = app.hVerticalLines(k);
                        %  disp(app.hLineToDrag(1).xl.Tag)
                        return;
                    end
                end

                if numel(app.hVerticalLines)>1 && not(isempty(app.hVerticalLines(1).xl))&&  isvalid(app.hVerticalLines(1).xl) && not(isempty(app.hVerticalLines(2).xl))&&  isvalid(app.hVerticalLines(2).xl)&& get(app.hVerticalLines(1).xl,'Value')<abs(event.IntersectionPoint(1)) && get(app.hVerticalLines(2).xl,'Value')>abs(event.IntersectionPoint(1))
                    app.hLineToDrag = app.hVerticalLines(1:2);
                    return;
                elseif numel(app.hVerticalLines)>3 && not(isempty(app.hVerticalLines(3).xl))&& isvalid(app.hVerticalLines(3).xl) && not(isempty(app.hVerticalLines(4).xl))&&  isvalid(app.hVerticalLines(4).xl)&& get(app.hVerticalLines(3).xl,'Value')<abs(event.IntersectionPoint(1)) && get(app.hVerticalLines(4).xl,'Value')>abs(event.IntersectionPoint(1))
                    app.hLineToDrag = app.hVerticalLines(3:4);
                    return;
                end
            end
        end

        % Window button motion function: UIFigure
        function UIFigureWindowButtonMotion(app, event)
            if ~isempty(app.hLineToDrag) && numel(app.hLineToDrag) > 0

                if startsWith(app.hLineToDrag(1).xl.Tag,'spot') 
                    currentPoint   = app.SpotAxes.CurrentPoint;
                    x            = currentPoint(1,1);
                    y            = currentPoint(1,2);
                    xlim_ = app.SpotAxes.XLim;
                    ylim_ = app.SpotAxes.YLim;
                    inside_SpotAxes = x >= xlim_(1) & x <= xlim_(2) & y >= ylim_(1) & y <= ylim_(2); % x >= xlim(1) & x <= xlim(2) & y >= ylim(1) & y <= ylim(2)
                    
                    %   inside_UNK_Plt=false;
                    % else
                    %
                    %     currentPoint   = app.UNK_Plt.CurrentPoint;
                    %     x            = currentPoint(1,1);
                    %     y            = currentPoint(1,2);
                    %     xlim_ = app.UNK_Plt.XLim;
                    %     ylim_ = app.UNK_Plt.YLim;
                    %     inside_UNK_Plt = x >= xlim_(1) & x <= xlim_(2) & y >= ylim_(1) & y <= ylim_(2); % x >= xlim(1) & x <= xlim(2) & y >= ylim(1) & y <= ylim(2)
                    %     inside_SpotAxes =false;
                end


                %if (inside_SpotAxes ) %|| inside_UNK_Plt

                if inside_SpotAxes
                    currentPoint   = app.SpotAxes.CurrentPoint;
                    x            = currentPoint(1,1);
                    %% check the position of the other lines
                    if isempty(app.hVerticalLinesBounds)
                        tmp=nan(1,4);
                        for n=1:4
                            if numel(app.hVerticalLines)>=n &&isvalid(app.hVerticalLines(n).xl) && not(isequal(app.hVerticalLines(n).xl,app.hLineToDrag(1).xl))
                                tmp(n)=[app.hVerticalLines(n).xl.Value];
                            end
                        end
                    end


                    % elseif inside_UNK_Plt
                    %     currentPoint   = app.UNK_Plt.CurrentPoint;
                    %     x            = currentPoint(1,1);
                    %     %  disp('unk')
                    %     if isempty(app.hVerticalLinesBounds)
                    %         tmp=nan(1,4);
                    %         for n=5:16
                    %             if numel(app.hVerticalLines)>=n && not(isempty(app.hVerticalLines(n).xl)) && isvalid(app.hVerticalLines(n).xl) && not(isequal(app.hVerticalLines(n).xl,app.hLineToDrag(1).xl))
                    %                 tmp(n-4)=[app.hVerticalLines(n).xl.Value];
                    %             end
                    %         end
                    %     end
                    %
                    % end

                    if isempty(app.hVerticalLinesBounds)
                        tmp=tmp(not(ismember(tmp,get(app.hLineToDrag(1).xl,'Value'))));
                        if numel(app.hLineToDrag)==2
                            tmp=tmp(not(ismember(tmp,get(app.hLineToDrag(2).xl,'Value'))));
                        end

                        tmp_larger=[tmp(tmp>x) max(xlim_)];
                        tmp_smaller=[tmp(tmp<x) min(xlim_)];
                        if numel(app.hLineToDrag)==2
                            tmp_larger=tmp_larger-abs(app.hLineToDrag(2).xl.Value-app.hLineToDrag(1).xl.Value)./2;
                            tmp_smaller=tmp_smaller+abs(app.hLineToDrag(2).xl.Value-app.hLineToDrag(1).xl.Value)./2;
                        end

                        [~, ix_larger]=min(abs(x-tmp_larger));
                        [~, ix_smaller]=min(abs(x-tmp_smaller));

                        upperbound=tmp_larger(ix_larger);
                        lowerbound=tmp_smaller(ix_smaller);
                        app.hVerticalLinesBounds=[lowerbound,upperbound];
                    else
                        lowerbound=app.hVerticalLinesBounds(1);
                        upperbound=app.hVerticalLinesBounds(2);
                    end
                    inside_bounds = x > lowerbound & x < upperbound;

                    if   inside_bounds
                        if numel(app.hLineToDrag)==2

                            xposlines=[get(app.hLineToDrag(1).xl,'Value') get(app.hLineToDrag(2).xl,'Value')];
                            difference=range(xposlines)/2; % here get the difference between the lines

                            x1=x-difference;
                            x2=x+difference;

                            set(app.hLineToDrag(1).xl, 'Value', x1);
                            set(app.hLineToDrag(2).xl, 'Value', x2);

                            %  xposlines=[get(app.hVerticalLines(1),'Value') get(app.hVerticalLines(2),'Value')];
                            %                         app.EditField_xlimMin.Value = min(xposlines);
                            %                         app.EditField_xlimMax.Value = max(xposlines);

                        else
                            set(app.hLineToDrag(1).xl, 'Value', x);
                            %disp(app.hLineToDrag(1).xl.Tag)
                            %  xposlines=[get(app.hVerticalLines(1),'Value') get(app.hVerticalLines(2),'Value')];
                            %                         app.EditField_xlimMin.Value = min(xposlines);
                            %                         app.EditField_xlimMax.Value = max(xposlines);
                        end
                        if inside_SpotAxes
                            update_interval_areas(app, 'all'); % Update patches for UNK plot
                        end


                    end
                else
                    UIFigureWindowButtonUp(app)
                end

            end
        end

        % Window button up function: UIFigure
        function UIFigureWindowButtonUp(app, event)

            
            %% here the interval values are refreshed when the intervals were moved

            if ~isempty(app.hLineToDrag)
                if not(isempty(app.hLineToDrag)) && isvalid(app.hLineToDrag(1).xl)

                    %% Background window
                    if strcmp(app.hLineToDrag(1).xl.Tag,'spot_bgwindow')

                        % get the position of the vertical lines, these
                        % positions can be anywhere on the x-axis, this is why we
                        % need to find the closest index first and then
                        % get the real time value
                        xpos = sort([app.hVerticalLines(1).xl.Value,app.hVerticalLines(2).xl.Value]);

                        [~, idx1] = min(abs(app.active_spot_indices - xpos(1)));
                        [~, idx2] = min(abs(app.active_spot_indices - xpos(2)));                  

                        % Update the background start and end indices
                        app.base.project.session.spot.background_starts{app.selected_spot} = app.active_spot_indices(idx1);
                        app.base.project.session.spot.background_ends{app.selected_spot} = app.active_spot_indices(idx2);

                        %% Matrix window
                    elseif strcmp(app.hLineToDrag(1).xl.Tag,'spot_matwindow')
                        %     disp('Update_Std_signalwindow')
                        xpos = sort([app.hVerticalLines(3).xl.Value,app.hVerticalLines(4).xl.Value]); % position of line

                        [~, idx1] = min(abs(app.active_spot_indices - xpos(1)));
                        [~, idx2] = min(abs(app.active_spot_indices - xpos(2)));                  

                        % Update the background start and end indices
                        app.base.project.session.spot.matrix_starts{app.selected_spot} = app.active_spot_indices(idx1);
                        app.base.project.session.spot.matrix_ends{app.selected_spot} = app.active_spot_indices(idx2);

                    
                        % elseif strcmp(app.hLineToDrag(1).xl.Tag,'UNK_bgwindow')
                        %     %% continue with the rest in the same way
                        %     sort([app.hVerticalLines(5).xl.Value,app.hVerticalLines(6).xl.Value]); % position of line
                        % elseif strcmp(app.hLineToDrag(1).xl.Tag,'UNK_mat1window')
                        %     sort([app.hVerticalLines(7).xl.Value,app.hVerticalLines(8).xl.Value]); % position of line
                        % elseif strcmp(app.hLineToDrag(1).xl.Tag,'UNK_mat2window')
                        %     sort([app.hVerticalLines(9).xl.Value,app.hVerticalLines(10).xl.Value]); % position of line
                        % elseif strcmp(app.hLineToDrag(1).xl.Tag,'UNK_comp1window')
                        %     sort([app.hVerticalLines(11).xl.Value,app.hVerticalLines(12).xl.Value]); % position of line
                        % elseif strcmp(app.hLineToDrag(1).xl.Tag,'UNK_comp2window')
                        %     sort([app.hVerticalLines(13).xl.Value,app.hVerticalLines(14).xl.Value]); % position of line
                        % elseif strcmp(app.hLineToDrag(1).xl.Tag,'UNK_comp3window')
                        %     sort([app.hVerticalLines(15).xl.Value,app.hVerticalLines(16).xl.Value]); % position of line

                    end
                end

                % app.hVerticalLinesBounds=[];
                % app.hLineToDrag = [];
                % % update_areas(xx)
                % update_areas(app,'all')
                app.hVerticalLinesBounds=[];
                app.hLineToDrag = [];
                update_interval_areas(app,'all')

            end
        end

        % Menu selected function: SaveSessiontoBaseandExitMenu
        function SaveSessiontoBaseandExitMenuSelected(app, event)
            if isempty(app.base) || isempty(app.base.project)
                delete(app)
                return
            end

            settings.LIBRA_GUI_update_state = true;

            save_to_base(app.sourceapp, app.project_id, app.base.project.session, app.session_id, app.base.project_name, settings)

            delete(app)
        end

        % Value changed function: PlotstylesListBox
        function PlotstylesListBoxValueChanged(app, event)
            app.selected_plotstyle = app.PlotstylesListBox.Value;
            app.selected_analytes = []; % empty the selected_analytes list when the plotstyle is changed so that it resets to show all again

            update_Interval_Module_GUI(app)


        end

        % Value changed function: ShowLegendCheckBox
        function ShowLegendCheckBoxValueChanged(app, event)
           
            update_Interval_Module_GUI(app)
        end

        % Value changed function: ShowIntervalsCheckBox
        function ShowIntervalsCheckBoxValueChanged(app, event)
            update_Interval_Module_GUI(app)
            
        end

        % Button pushed function: RefreshButton
        function RefreshButtonPushed(app, event)
            get_plotstyles(app)
            update_Interval_Module_GUI(app)
        end

        % Value changed function: CommentTextArea
        function CommentTextAreaValueChanged(app, event)
            comment = app.CommentTextArea.Value;

            % Ensure the comment array exists and is the correct size
            if ~isfield(app.base.project.session.spot, 'comment') || isempty(app.base.project.session.spot.comment)
                app.base.project.session.spot.comment = cell(numel(app.base.project.session.spot.signal_starts), 1);
            end

            % Store the comment in correct spot entry
            app.base.project.session.spot.comment{app.selected_spot} = comment;

        end

        % Button pushed function: ErasebackgroundsgloballyButton
        function ErasebackgroundsgloballyButtonPushed(app, event)
            app.base.project.session.spot.background_starts = arrayfun(@(x) [], ones(size(app.base.project.session.spot.signal_starts)), 'UniformOutput', false);
            app.base.project.session.spot.background_ends = arrayfun(@(x) [], ones(size(app.base.project.session.spot.signal_ends)), 'UniformOutput', false);

            update_Interval_Module_GUI(app)
        end

        % Button pushed function: ErasematrixintervalsgloballyButton
        function ErasematrixintervalsgloballyButtonPushed(app, event)
                        
            app.base.project.session.spot.matrix_starts = arrayfun(@(x) [], ones(size(app.base.project.session.spot.signal_starts)), 'UniformOutput', false);
            app.base.project.session.spot.matrix_ends = arrayfun(@(x) [], ones(size(app.base.project.session.spot.signal_ends)), 'UniformOutput', false);

            update_Interval_Module_GUI(app)
        end

        % Button pushed function: SetBackgroundButton
        function SetBackgroundButtonPushed(app, event)
            draw_interval_via_rectangles(app, "Background")
            
            update_Interval_Module_GUI(app)
        end

        % Button pushed function: SetMatrixButton
        function SetMatrixButtonPushed(app, event)
            draw_interval_via_rectangles(app, "Matrix")

            update_Interval_Module_GUI(app)
        end

        % Button pushed function: DeleteBackgroundButton
        function DeleteBackgroundButtonPushed(app, event)
            app.base.project.session.spot.background_starts{app.selected_spot} = [];
            app.base.project.session.spot.background_ends{app.selected_spot} = [];
            update_Interval_Module_GUI(app)
        end

        % Button pushed function: DeleteMatrixButton
        function DeleteMatrixButtonPushed(app, event)
            app.base.project.session.spot.matrix_starts{app.selected_spot} = [];
            app.base.project.session.spot.matrix_ends{app.selected_spot} = [];
            update_Interval_Module_GUI(app)
        end

        % Button pushed function: EliminateSpikesButton
        function EliminateSpikesButtonPushed(app, event)
            if isfield(app.base.project.session, 'ms_data')

                data = table2array(app.base.project.session.ms_data(app.active_spot_indices, 2:end));
                [corrected_data] = spike_elimination(app, data);
                app.base.project.session.ms_data(app.active_spot_indices, 2:end) = array2table(corrected_data);

            elseif isfield(app.base.project.session.spot, 'ms_data_single_spot')

                data = table2array(app.base.project.session.spot.ms_data_single_spot{app.selected_spot}(:, 2:end));
                [corrected_data] = spike_elimination(app, data);
                app.base.project.session.spot.ms_data_single_spot{app.selected_spot}(:, 2:end) = array2table(corrected_data);

            end

            update_Interval_Module_GUI(app)
        end

        % Button pushed function: EliminateSpikesGloballyButton
        function EliminateSpikesGloballyButtonPushed(app, event)
            if isfield(app.base.project.session, 'ms_data')

                data = table2array(app.base.project.session.ms_data(:, 2:end));
                [corrected_data] = spike_elimination(app, data);
                app.base.project.session.ms_data(:, 2:end) = array2table(corrected_data);

            elseif isfield(app.base.project.session.spot, 'ms_data_single_spot')

                % Iterate through all spots
                for spot_idx = 1:length(app.base.project.session.spot.ms_data_single_spot)
                    data = table2array(app.base.project.session.spot.ms_data_single_spot{spot_idx}(:, 2:end));
                    [corrected_data] = spike_elimination(app, data);
                    app.base.project.session.spot.ms_data_single_spot{spot_idx}(:, 2:end) = array2table(corrected_data);
                end

            end

            update_Interval_Module_GUI(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.WindowButtonUpFcn = createCallbackFcn(app, @UIFigureWindowButtonUp, true);
            app.UIFigure.WindowButtonMotionFcn = createCallbackFcn(app, @UIFigureWindowButtonMotion, true);
            app.BaseMenu = uimenu(app.UIFigure);
            app.BaseMenu.Text = 'Base';
            app.SaveSessiontoBaseandExitMenu = uimenu(app.BaseMenu);
            app.SaveSessiontoBaseandExitMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveSessiontoBaseandExitMenuSelected, true);
            app.SaveSessiontoBaseandExitMenu.Text = 'Save Session to Base and Exit';
            app.SpotAxes = uiaxes(app.UIFigure);
            title(app.SpotAxes, 'Title')
            xlabel(app.SpotAxes, 'Sweeps')
            ylabel(app.SpotAxes, 'Intensity [cps]')
            zlabel(app.SpotAxes, 'Z')
            app.SpotAxes.ButtonDownFcn = createCallbackFcn(app, @SpotAxesButtonDown, true);
            app.ImportButton = uibutton(app.UIFigure, 'push');
            app.ImportButton.Text = 'Import';
            app.IntervalButton = uibutton(app.UIFigure, 'push');
            app.IntervalButton.Text = 'Interval';
            app.ElementLegendListBox = uilistbox(app.UIFigure);
            app.ElementLegendListBox.Items = {''};
            app.ElementLegendListBox.Multiselect = 'on';
            app.ElementLegendListBox.ValueChangedFcn = createCallbackFcn(app, @ElementLegendListBoxValueChanged, true);
            app.ElementLegendListBox.Value = {''};
            app.NextSignalButton = uibutton(app.UIFigure, 'push');
            app.NextSignalButton.ButtonPushedFcn = createCallbackFcn(app, @NextSignalButtonPushed, true);
            app.NextSignalButton.Text = 'Next';
            app.SpotListBox = uilistbox(app.UIFigure);
            app.SpotListBox.Items = {''};
            app.SpotListBox.ValueChangedFcn = createCallbackFcn(app, @SpotListBoxValueChanged, true);
            app.SpotListBox.Value = '';
            app.PreviousSignalButton = uibutton(app.UIFigure, 'push');
            app.PreviousSignalButton.ButtonPushedFcn = createCallbackFcn(app, @PreviousSignalButtonPushed, true);
            app.PreviousSignalButton.Text = 'Previous';
            app.PlotstylesListBox = uilistbox(app.UIFigure);
            app.PlotstylesListBox.Items = {''};
            app.PlotstylesListBox.ValueChangedFcn = createCallbackFcn(app, @PlotstylesListBoxValueChanged, true);
            app.PlotstylesListBox.Value = '';
            app.PlotBackgroundcorrectedCheckBox = uicheckbox(app.UIFigure);
            app.PlotBackgroundcorrectedCheckBox.ValueChangedFcn = createCallbackFcn(app, @PlotBackgroundcorrectedCheckBoxValueChanged, true);
            app.PlotBackgroundcorrectedCheckBox.Text = 'Plot Background-corrected';
            app.ZoomintoSignalCheckBox = uicheckbox(app.UIFigure);
            app.ZoomintoSignalCheckBox.ValueChangedFcn = createCallbackFcn(app, @ZoomintoSignalCheckBoxValueChanged, true);
            app.ZoomintoSignalCheckBox.Text = 'Zoom in to Signal';
            app.SetbackgroundintervalsautomaticallyButton = uibutton(app.UIFigure, 'push');
            app.SetbackgroundintervalsautomaticallyButton.ButtonPushedFcn = createCallbackFcn(app, @SetbackgroundintervalsautomaticallyButtonPushed, true);
            app.SetbackgroundintervalsautomaticallyButton.Text = 'Set background intervals automatically';
            app.SetmatrixintervalsautomaticallyButton = uibutton(app.UIFigure, 'push');
            app.SetmatrixintervalsautomaticallyButton.ButtonPushedFcn = createCallbackFcn(app, @SetmatrixintervalsautomaticallyButtonPushed, true);
            app.SetmatrixintervalsautomaticallyButton.Text = 'Set matrix intervals automatically';
            app.ClassificationButton = uibutton(app.UIFigure, 'push');
            app.ClassificationButton.Text = 'Classification';
            app.DriftCorrectionButton = uibutton(app.UIFigure, 'push');
            app.DriftCorrectionButton.Text = 'Drift Correction';
            app.QuantificationButton = uibutton(app.UIFigure, 'push');
            app.QuantificationButton.Text = 'Quantification';
            app.BgLowerLimitMinusOneButton = uibutton(app.UIFigure, 'push');
            app.BgLowerLimitMinusOneButton.ButtonPushedFcn = createCallbackFcn(app, @BgLowerLimitMinusOneButtonPushed, true);
            app.BgLowerLimitMinusOneButton.Text = '-1';
            app.BgLowerLimitPlusOneButton = uibutton(app.UIFigure, 'push');
            app.BgLowerLimitPlusOneButton.ButtonPushedFcn = createCallbackFcn(app, @BgLowerLimitPlusOneButtonPushed, true);
            app.BgLowerLimitPlusOneButton.Text = '+1';
            app.BgLowerLimitMinusTenButton = uibutton(app.UIFigure, 'push');
            app.BgLowerLimitMinusTenButton.ButtonPushedFcn = createCallbackFcn(app, @BgLowerLimitMinusTenButtonPushed, true);
            app.BgLowerLimitMinusTenButton.Text = '-10';
            app.BgLowerLimitPlusTenButton = uibutton(app.UIFigure, 'push');
            app.BgLowerLimitPlusTenButton.ButtonPushedFcn = createCallbackFcn(app, @BgLowerLimitPlusTenButtonPushed, true);
            app.BgLowerLimitPlusTenButton.Text = '+10';
            app.BgUpperLimitMinusOneButton = uibutton(app.UIFigure, 'push');
            app.BgUpperLimitMinusOneButton.ButtonPushedFcn = createCallbackFcn(app, @BgUpperLimitMinusOneButtonPushed, true);
            app.BgUpperLimitMinusOneButton.Text = '-1';
            app.BgUpperLimitPlusOneButton = uibutton(app.UIFigure, 'push');
            app.BgUpperLimitPlusOneButton.ButtonPushedFcn = createCallbackFcn(app, @BgUpperLimitPlusOneButtonPushed, true);
            app.BgUpperLimitPlusOneButton.Text = '+1';
            app.BgUpperLimitMinusTenButton = uibutton(app.UIFigure, 'push');
            app.BgUpperLimitMinusTenButton.ButtonPushedFcn = createCallbackFcn(app, @BgUpperLimitMinusTenButtonPushed, true);
            app.BgUpperLimitMinusTenButton.Text = '-10';
            app.BgUpperLimitPlusTenButton = uibutton(app.UIFigure, 'push');
            app.BgUpperLimitPlusTenButton.ButtonPushedFcn = createCallbackFcn(app, @BgUpperLimitPlusTenButtonPushed, true);
            app.BgUpperLimitPlusTenButton.Text = '+10';
            app.GlobalLowerLimitLabel = uilabel(app.UIFigure);
            app.GlobalLowerLimitLabel.Text = 'Global Lower Limit:';
            app.GlobalUpperLimitLabel = uilabel(app.UIFigure);
            app.GlobalUpperLimitLabel.Text = 'Global Upper Limit:';
            app.MatrixLowerLimitMinusOneButton = uibutton(app.UIFigure, 'push');
            app.MatrixLowerLimitMinusOneButton.ButtonPushedFcn = createCallbackFcn(app, @MatrixLowerLimitMinusOneButtonPushed, true);
            app.MatrixLowerLimitMinusOneButton.Text = '-1';
            app.MatrixLowerLimitPlusOneButton = uibutton(app.UIFigure, 'push');
            app.MatrixLowerLimitPlusOneButton.ButtonPushedFcn = createCallbackFcn(app, @MatrixLowerLimitPlusOneButtonPushed, true);
            app.MatrixLowerLimitPlusOneButton.Text = '+1';
            app.MatrixLowerLimitMinusTenButton = uibutton(app.UIFigure, 'push');
            app.MatrixLowerLimitMinusTenButton.ButtonPushedFcn = createCallbackFcn(app, @MatrixLowerLimitMinusTenButtonPushed, true);
            app.MatrixLowerLimitMinusTenButton.Text = '-10';
            app.MatrixLowerLimitPlusTenButton = uibutton(app.UIFigure, 'push');
            app.MatrixLowerLimitPlusTenButton.ButtonPushedFcn = createCallbackFcn(app, @MatrixLowerLimitPlusTenButtonPushed, true);
            app.MatrixLowerLimitPlusTenButton.Text = '+10';
            app.MatrixUpperLimitMinusOneButton = uibutton(app.UIFigure, 'push');
            app.MatrixUpperLimitMinusOneButton.ButtonPushedFcn = createCallbackFcn(app, @MatrixUpperLimitMinusOneButtonPushed, true);
            app.MatrixUpperLimitMinusOneButton.Text = '-1';
            app.MatrixUpperLimitPlusOneButton = uibutton(app.UIFigure, 'push');
            app.MatrixUpperLimitPlusOneButton.ButtonPushedFcn = createCallbackFcn(app, @MatrixUpperLimitPlusOneButtonPushed, true);
            app.MatrixUpperLimitPlusOneButton.Text = '+1';
            app.MatrixUpperLimitMinusTenButton = uibutton(app.UIFigure, 'push');
            app.MatrixUpperLimitMinusTenButton.ButtonPushedFcn = createCallbackFcn(app, @MatrixUpperLimitMinusTenButtonPushed, true);
            app.MatrixUpperLimitMinusTenButton.Text = '-10';
            app.MatrixUpperLimitPlusTenButton = uibutton(app.UIFigure, 'push');
            app.MatrixUpperLimitPlusTenButton.ButtonPushedFcn = createCallbackFcn(app, @MatrixUpperLimitPlusTenButtonPushed, true);
            app.MatrixUpperLimitPlusTenButton.Text = '+10';
            app.bg_lower_indentEditField = uieditfield(app.UIFigure, 'numeric');
            app.bg_lower_indentEditField.ValueChangedFcn = createCallbackFcn(app, @bg_lower_indentEditFieldValueChanged, true);
            app.bg_lower_indentEditField.Value = 20;
            app.bg_upper_indentEditField = uieditfield(app.UIFigure, 'numeric');
            app.bg_upper_indentEditField.ValueChangedFcn = createCallbackFcn(app, @bg_upper_indentEditFieldValueChanged, true);
            app.bg_upper_indentEditField.Value = 17;
            app.matrix_lower_indentEditField = uieditfield(app.UIFigure, 'numeric');
            app.matrix_lower_indentEditField.ValueChangedFcn = createCallbackFcn(app, @matrix_lower_indentEditFieldValueChanged, true);
            app.matrix_lower_indentEditField.Value = 5;
            app.matrix_upper_indentEditField = uieditfield(app.UIFigure, 'numeric');
            app.matrix_upper_indentEditField.ValueChangedFcn = createCallbackFcn(app, @matrix_upper_indentEditFieldValueChanged, true);
            app.matrix_upper_indentEditField.Value = 5;
            app.EliminateSpikesButton = uibutton(app.UIFigure, 'push');
            app.EliminateSpikesButton.ButtonPushedFcn = createCallbackFcn(app, @EliminateSpikesButtonPushed, true);
            app.EliminateSpikesButton.Text = 'Eliminate Spikes';
            app.LogscaleCheckBox = uicheckbox(app.UIFigure);
            app.LogscaleCheckBox.ValueChangedFcn = createCallbackFcn(app, @LogscaleCheckBoxValueChanged, true);
            app.LogscaleCheckBox.Text = 'Log-scale';
            app.LogscaleCheckBox.Value = true;
            app.RefreshButton = uibutton(app.UIFigure, 'push');
            app.RefreshButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshButtonPushed, true);
            app.RefreshButton.Text = 'Refresh';
            app.ProceedtoClassificationModuleButton = uibutton(app.UIFigure, 'push');
            app.ProceedtoClassificationModuleButton.ButtonPushedFcn = createCallbackFcn(app, @ProceedtoClassificationModuleButtonPushed, true);
            app.ProceedtoClassificationModuleButton.Text = 'Proceed to Classification Module';
            app.ShowIntervalsCheckBox = uicheckbox(app.UIFigure);
            app.ShowIntervalsCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowIntervalsCheckBoxValueChanged, true);
            app.ShowIntervalsCheckBox.Text = 'Show Intervals';
            app.ShowIntervalsCheckBox.Value = true;
            app.ShowLegendCheckBox = uicheckbox(app.UIFigure);
            app.ShowLegendCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowLegendCheckBoxValueChanged, true);
            app.ShowLegendCheckBox.Text = 'Show Legend';
            app.CommentTextArea = uitextarea(app.UIFigure);
            app.CommentTextArea.ValueChangedFcn = createCallbackFcn(app, @CommentTextAreaValueChanged, true);
            app.ErasebackgroundsgloballyButton = uibutton(app.UIFigure, 'push');
            app.ErasebackgroundsgloballyButton.ButtonPushedFcn = createCallbackFcn(app, @ErasebackgroundsgloballyButtonPushed, true);
            app.ErasebackgroundsgloballyButton.Text = 'Erase backgrounds globally';
            app.ErasematrixintervalsgloballyButton = uibutton(app.UIFigure, 'push');
            app.ErasematrixintervalsgloballyButton.ButtonPushedFcn = createCallbackFcn(app, @ErasematrixintervalsgloballyButtonPushed, true);
            app.ErasematrixintervalsgloballyButton.Text = 'Erase matrix intervals globally';
            app.SetBackgroundButton = uibutton(app.UIFigure, 'push');
            app.SetBackgroundButton.ButtonPushedFcn = createCallbackFcn(app, @SetBackgroundButtonPushed, true);
            app.SetBackgroundButton.Tooltip = {'Press d button on the keyboard to activate'};
            app.SetBackgroundButton.Text = 'Set Background';
            app.SetMatrixButton = uibutton(app.UIFigure, 'push');
            app.SetMatrixButton.ButtonPushedFcn = createCallbackFcn(app, @SetMatrixButtonPushed, true);
            app.SetMatrixButton.Tooltip = {'Press f button on the keyboard to activate'};
            app.SetMatrixButton.Text = 'Set Matrix';
            app.DeleteBackgroundButton = uibutton(app.UIFigure, 'push');
            app.DeleteBackgroundButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteBackgroundButtonPushed, true);
            app.DeleteBackgroundButton.Text = 'Delete Background';
            app.DeleteMatrixButton = uibutton(app.UIFigure, 'push');
            app.DeleteMatrixButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteMatrixButtonPushed, true);
            app.DeleteMatrixButton.Text = 'Delete Matrix';
            app.CommentLabel = uilabel(app.UIFigure);
            app.CommentLabel.Text = 'Comment:';
            app.EliminateSpikesGloballyButton = uibutton(app.UIFigure, 'push');
            app.EliminateSpikesGloballyButton.ButtonPushedFcn = createCallbackFcn(app, @EliminateSpikesGloballyButtonPushed, true);
            app.EliminateSpikesGloballyButton.Text = 'Eliminate Spikes Globally';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Interval_Module(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end