classdef Correction_Module < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        BaseMenu                   matlab.ui.container.Menu
        SavetoBaseandExitMenu      matlab.ui.container.Menu
        TabGroup                   matlab.ui.container.TabGroup
        CalibrationlineTab         matlab.ui.container.Tab
        TabGroup2                  matlab.ui.container.TabGroup
        FitTab                     matlab.ui.container.Tab
        QuadraticFitCheckBox       matlab.ui.control.CheckBox
        IntStdappliedLabel         matlab.ui.control.Label
        AnalyteDropDown            matlab.ui.control.DropDown
        ContainsEditField          matlab.ui.control.EditField
        ContainsEditField_2Label   matlab.ui.control.Label
        cEditField                 matlab.ui.control.NumericEditField
        xEditField                 matlab.ui.control.NumericEditField
        xLabel_2                   matlab.ui.control.Label
        x2EditField                matlab.ui.control.NumericEditField
        x2Label_2                  matlab.ui.control.Label
        LogscaleCheckBox           matlab.ui.control.CheckBox
        bracket_idDropDown         matlab.ui.control.DropDown
        bracket_idDropDownLabel    matlab.ui.control.Label
        ISappliedDropDown          matlab.ui.control.DropDown
        PRMforFitDropDown          matlab.ui.control.DropDown
        PRMLabel                   matlab.ui.control.Label
        Label                      matlab.ui.control.Label
        SpotnameTable              matlab.ui.control.Table
        IncludeButton              matlab.ui.control.Button
        ExcludeButton              matlab.ui.control.Button
        IncludeallQCRMButton       matlab.ui.control.Button
        ResetButton_true           matlab.ui.control.Button
        ApplycorrectionfromfitshowninplotButton_true  matlab.ui.control.Button
        CalibrationAxes            matlab.ui.control.UIAxes
        CorrectedDataTab           matlab.ui.container.Tab
        GridLayout2                matlab.ui.container.GridLayout
        ResetTableButton           matlab.ui.control.Button
        DisplayDropDown            matlab.ui.control.DropDown
        DisplayDropDownLabel       matlab.ui.control.Label
        ShowDropDown               matlab.ui.control.DropDown
        ShowDropDownLabel          matlab.ui.control.Label
        BracketidDropDown_3        matlab.ui.control.DropDown
        BracketidDropDown_3Label   matlab.ui.control.Label
        CalibratorDropDown_2       matlab.ui.control.DropDown
        CalibratorDropDown_2Label  matlab.ui.control.Label
        CreateOutputTableButton    matlab.ui.control.Button
        ResultsTable               matlab.ui.control.Table
        QualityControlofCorrectedDataTab  matlab.ui.container.Tab
        DisplayDropDown_2          matlab.ui.control.DropDown
        DisplayDropDown_2Label     matlab.ui.control.Label
        QualityControlReferenceMaterialDropDown_2  matlab.ui.control.DropDown
        QualityControlReferenceMaterialDropDown_2Label  matlab.ui.control.Label
        BracketidDropDown_2        matlab.ui.control.DropDown
        BracketidDropDown_2Label   matlab.ui.control.Label
        CalibratorDropDown_3       matlab.ui.control.DropDown
        CalibratorDropDown_3Label  matlab.ui.control.Label
        SRMAxes                    matlab.ui.control.UIAxes
        QCRMcorrectionTab          matlab.ui.container.Tab
        TabGroup3                  matlab.ui.container.TabGroup
        PlotTab                    matlab.ui.container.Tab
        NotimplementedyetLabel     matlab.ui.control.Label
        QualityControlReferenceMaterialDropDown  matlab.ui.control.DropDown
        QualityControlReferenceMaterialDropDownLabel  matlab.ui.control.Label
        BracketidDropDown          matlab.ui.control.DropDown
        BracketidDropDownLabel     matlab.ui.control.Label
        CalibratorDropDown         matlab.ui.control.DropDown
        PrimaryReferenceMaterialDropDown_3Label_2  matlab.ui.control.Label
        SRMAxes_2                  matlab.ui.control.UIAxes
        CorrectedDataTab_2         matlab.ui.container.Tab
    end


    properties (Access = public)
        base
        project_id
        session_id
    end


    properties (Access = private)
        sourceapp

        %%%
        selected_CAL_for_fit
        selected_bracket_id
        selected_spot
        selected_string
        selected_QCRM
        selected_CAL

        %%%
        editstatus = false % once this is true it asks if the data should be saved

        sensitivity_relative_to_internal_standard
        internal_standard_indices_total_mass


    end

    methods (Access = private)

        function DisplayError(app, ME,WhichOperation)
            %DisplayError - Displays detailed error information
            % Description:
            %    This function displays detailed information about an error, including the
            %    time of occurrence, the operation during which the error occurred, and the
            %    stack trace of the error. If the WhichOperation parameter is not provided,
            %    it defaults to '?'. In deployed software the error message is written into the log_file.txt
            % Author: Nils B. Gies
            % Last edit: 2025-01-05
            % Inputs:
            %    ME - The MException object containing error information
            %    WhichOperation - A string describing the operation during which the error occurred
            %
            try
                if not(exist("WhichOperation","var"))
                    disp('Error! Missing ME!')
                    return
                end

                if not(exist("WhichOperation","var"))
                    WhichOperation='?';
                end

                disp(' ')
                disp('_-_-_-_-_-_-_-_-_-_-_-_-_-_-_-_-_-_-_-_-_')
                disp('%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%')
                disp('%%%%%%%%%%%%%%   Error   %%%%%%%%%%%%%%%%')
                disp('%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%')
                disp('%%')
                disp(['%%        ' char(datetime('now'))])
                disp('%%')
                disp('%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%')
                disp(['Error occured during: ' WhichOperation])
                % for n=1
                disp('_________________________________________')
                k=1;
                for n=numel(ME.stack):-1:1
                    disp(['%%' num2str(k,'%03.f') '%%'])
                    k=1+k;
                    disp('Error in file:')
                    disp(ME.stack(n).file)
                    disp(['Error in line: ',num2str(ME.stack(n).line)])
                    disp('_________________________________________')
                    disp('  ')
                end
                disp(['Error in message: ' ME.message])
                disp('  ')
                disp('%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%')
                disp('_-_-_-_-_-_-_-_-_-_-_-_-_-_-_-_-_-_-_-_-_')
                disp('  ')
                disp('  ')
            catch ME
                disp('Error occured during: DisplayError(app, ME,WhichOperation)')
                for n=numel(ME.stack):-1:1
                    disp('Error in file:')
                    disp(ME.stack(n).file)
                    disp(['Error in line: ',num2str(ME.stack(n).line)])
                    disp('_________________________________________')
                    disp('  ')
                end
            end
        end


        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% Calibration Line %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

        function update_fit_tab(app)

            % Get indices where type is "CAL"
            cal_indices = strcmp(app.base.project.session.spot.type, "CAL");
            % Get reference materials only for CAL spots
            cal_materials = app.base.project.session.spot.referencematerial(cal_indices);
            % Get unique names, excluding "None" and "Select Reference Material"
            unique_names = unique(cal_materials);
            unique_names = unique_names(~strcmp(unique_names, 'None'));
            validCALs = unique_names(~strcmp(unique_names, 'Select Reference Material'));

            % Populate CAL dropdown
            app.CALforFitDropDown.Items = validCALs;
            app.CALforFitDropDown.ItemsData = 1:numel(app.CALforFitDropDown.Items);

            % at startup the selected_CAL is empty, make it 1 so that
            % something is plotted anyways
            if isempty(app.selected_CAL_for_fit)
                app.selected_CAL_for_fit = 1;
            end

            % Get selected reference material and find matching indices
            selectedRM = validCALs(app.CALforFitDropDown.Value);
            rmIndices = strcmp(app.base.project.session.spot.referencematerial, selectedRM);

            % Filter bracket IDs for selected reference material
            filteredBracketIds = app.base.project.session.spot.bracket_id(rmIndices);
            uniqueBracketIds = unique(filteredBracketIds);
            uniqueBracketIds = uniqueBracketIds(uniqueBracketIds ~= 0);

            % Update bracket ID dropdown with "All" option at the end
            app.bracket_idDropDown.Items = [string(uniqueBracketIds); "All"];
            app.bracket_idDropDown.ItemsData = [uniqueBracketIds; 0]; % Using 0 as the ItemsData for "All"

            app.AnalyteDropDown.Items = app.base.project.session.spot.sensitivities{1, app.selected_CAL_for_fit}.Properties.VariableNames';
            app.AnalyteDropDown.ItemsData = app.AnalyteDropDown.Items;


            update_spotname_value_table(app)
            update_spot_table_colours(app)

        end

        function update_spotname_value_table(app)


            % if ~isfield(app.base.project.session.spot, 'material') && ~isfield(app.base.project.session.spot, 'sample')
            %     % if the values contain all zeros means that the CAL was
            %     % not used
            %     message={'Add metadata (Sample, Material & Spotsize) in Results Module to fit new data. It is still possible to apply the correction with another fit though.'};
            %     uialert(app.UIFigure,message,' Warning');
            %     %return
            % end

            spotnames = app.base.project.session.spot.name(:);  % ensure column vector
            spotNumbers = (1:numel(spotnames))';  % create column vector of numbers
            % material = app.base.project.session.spot.material(:);
            % sample = app.base.project.session.spot.sample(:);
            % size = app.base.project.session.spot.size(:);

            % Generate initial table with spot numbers and names
            T = table(spotNumbers, spotnames, 'VariableNames', {'#', 'Spotname'});

            %T = table(spotNumbers, spotnames, material, sample, size, 'VariableNames', {'#', 'Spotname', 'Material', 'Sample', 'Size'});

            app.SpotnameTable.Data = T;
            app.SpotnameTable.ColumnName = T.Properties.VariableNames;

        end

        function update_spot_table_colours(app)
            for i = 1:height(app.SpotnameTable.Data)
                if isequal(app.base.project.session.spot.correction.calibration_line.spot_to_fit(i), 1)
                    app.SpotnameTable.BackgroundColor(i, :) = [204/255, 1, 204/255]; % Light green
                else
                    app.SpotnameTable.BackgroundColor(i, :) = [1, 1, 1]; % White
                end
            end
        end


        %%%%%%%%%%%%%%%% Calibration line fit %%%%%%%%%%%%%%%%%%%%%

        % original with horizontal errorbars
        function update_calibration_plot(app)
            %%%%%%%%%%%%%% get the measured values
            % then get the measured or corrected Cl value
            if isequal(app.ISappliedDropDown.Value, 'Yes')
                % Get matrix composition data
                mass_fraction_values = app.base.project.session.spot.matrix_composition;
            elseif isequal(app.ISappliedDropDown.Value, 'No')
                % Get the sensitivity tables
                sensitivity = app.base.project.session.spot.sensitivities;

                % Create a copy of sensitivity structure to store preliminary mass fractions
                mass_fraction_values = sensitivity;

                % Get Cl35 intensities
                intensity = app.base.project.session.spot.bkgd_corrected_cps.(app.AnalyteDropDown.Value);

                % Loop through each bracket and calculate preliminary mass fractions
                for i = 1:size(sensitivity, 1)
                    for j = 1:size(sensitivity, 2)
                        % For each bracket and CAL, keep the same table structure
                        % Only divide where sensitivity is not zero
                        nonzero_indices = sensitivity{i,j}.(app.AnalyteDropDown.Value) ~= 0;

                        % Initialize with the same structure (keep zeros as zeros)
                        mass_fraction_values{i,j}.(app.AnalyteDropDown.Value) = sensitivity{i,j}.(app.AnalyteDropDown.Value);

                        % Only calculate mass fractions for non-zero sensitivity values
                        if any(nonzero_indices)
                            % Extract corresponding intensities for non-zero sensitivity spots
                            relevant_intensities = intensity(nonzero_indices);
                            relevant_sensitivities = sensitivity{i,j}.(app.AnalyteDropDown.Value)(nonzero_indices);

                            % Calculate preliminary mass fraction
                            mass_fraction_values{i,j}.(app.AnalyteDropDown.Value)(nonzero_indices) = relevant_intensities ./ relevant_sensitivities;
                        end
                    end
                end
            end

            % Get Cl35 values from matrix_comp table
            bracket_id = app.bracket_idDropDown.Value;

            % Extract Cl35 values based on bracket_id
            if ~isequal(bracket_id, 0)
                % For specific bracket, extract just that table
                analyte_conc_table = mass_fraction_values{bracket_id, app.selected_CAL_for_fit};
                analyte_conc = analyte_conc_table.(app.AnalyteDropDown.Value);

                %%%%%%%%%%%%%%%%%%%%%
                % Get logical array of spots to fit
                spots_to_fit = app.base.project.session.spot.correction.calibration_line.spot_to_fit;

                % First filter these to only get QCRM
                qcrm_indices = strcmp(app.base.project.session.spot.type, "QCRM");

                % Combine masks to get only QCRM spots that are selected for fitting
                qcrm_spots_to_fit = spots_to_fit & qcrm_indices;

                measured_analyte_values = analyte_conc(qcrm_spots_to_fit);

                refmat_name = app.base.project.session.spot.referencematerial(qcrm_spots_to_fit);

                %%%% get the true values
                % Initialize storage for Cl reference values
                ref_cl_values = zeros(size(refmat_name));

                % Get the reference materials data and names
                ref_mat_names = app.base.project.session.referencematerials.names;
                ref_mat_data = app.base.project.session.referencematerials.data;

                % Loop through each reference material in refmat_name
                for i = 1:length(refmat_name)
                    current_refmat = refmat_name(i);

                    % Find the index of this reference material in the reference materials list
                    ref_idx = find(strcmp(ref_mat_names, current_refmat));

                    if ~isempty(ref_idx)
                        % Get the reference data table for this material
                        ref_table = ref_mat_data{ref_idx};

                        elementName = regexprep(app.AnalyteDropDown.Value, '[0-9]', '');

                        % Check if Cl column exists in the table
                        if any(strcmp(ref_table.Properties.VariableNames, elementName))
                            % Get the Cl value
                            ref_cl_values(i) = ref_table.(elementName);
                        else
                            % If Cl is not in the table, mark as NaN
                            ref_cl_values(i) = NaN;
                        end
                    else
                        % Reference material not found in database
                        ref_cl_values(i) = NaN;
                    end
                end

                % Clear the axes first
                cla(app.CalibrationAxes, 'reset');

                % Get unique reference materials for coloring
                unique_refmats = unique(refmat_name);
                colors = lines(length(unique_refmats)); % Generate distinct colors

                % Initialize legend entries
                legend_entries = cell(length(unique_refmats), 1);

                hold(app.CalibrationAxes, 'on');

                % Initialize containers for averaging
                avg_measured = [];
                avg_reference = [];
                std_measured = [];
                refmat_for_avg = {};

                % For each reference material, calculate the average and standard deviation
                for i = 1:length(unique_refmats)
                    current_mat = unique_refmats{i};
                    mask = strcmp(refmat_name, current_mat);

                    % Get values for this reference material
                    current_measured = measured_analyte_values(mask);
                    current_ref = ref_cl_values(mask);

                    % Filter out zeros and NaNs
                    valid_mask = current_measured > 0 & ~isnan(current_measured) & ~isnan(current_ref);
                    valid_measured = current_measured(valid_mask);
                    valid_reference = current_ref(valid_mask);

                    % Only process if we have valid data
                    if ~isempty(valid_measured) && ~isempty(valid_reference)
                        % Calculate mean and standard deviation
                        mean_measured = mean(valid_measured);
                        sd_measured = std(valid_measured);

                        % Store values (reference should be the same for all spots of this material)
                        avg_measured = [avg_measured; mean_measured];
                        avg_reference = [avg_reference; valid_reference(1)];
                        std_measured = [std_measured; sd_measured];
                        refmat_for_avg = [refmat_for_avg; current_mat];
                    end
                end

                % Plot averaged data points with error bars
                for i = 1:length(refmat_for_avg)
                    current_mat = refmat_for_avg{i};
                    % Find color index for this reference material
                    color_idx = find(strcmp(unique_refmats, current_mat));

                    % Plot averaged point with error bars
                    h_avg = errorbar(app.CalibrationAxes, avg_measured(i), avg_reference(i), 0, 0, std_measured(i), std_measured(i), ...
                        'o', 'Color', colors(color_idx,:), 'MarkerFaceColor', colors(color_idx,:), ...
                        'MarkerEdgeColor', 'k', 'LineWidth', 1.5, 'DisplayName', [current_mat ' (Avg ± σ)']);

                    % Add to legend
                    legend_entries{i} = h_avg;
                end

                if ~isempty(avg_measured) && ~isempty(avg_reference)
                    if app.QuadraticFitCheckBox.Value
                        % Quadratic fit through averaged points
                        p_avg = polyfit(avg_measured, avg_reference, 2);

                        % Generate points for plotting the fit
                        x_min = min(avg_measured);
                        x_max = max(avg_measured);
                        x_smooth = linspace(x_min, x_max, 500);
                        y_fit = polyval(p_avg, x_smooth);

                        % Plot the fit line
                        h_quad = plot(app.CalibrationAxes, x_smooth, y_fit, '-k', 'LineWidth', 2, 'DisplayName', 'Quadratic fit (Avg)');

                        % Generate error envelope (± 1 sigma prediction interval)
                        [p_avg, S] = polyfit(avg_measured, avg_reference, 2);
                        [y_pred, delta] = polyval(p_avg, x_smooth, S);
                        upper_bound = y_pred + delta;
                        lower_bound = y_pred - delta;

                        % Plot error envelope
                        x_envelope = [x_smooth, fliplr(x_smooth)];
                        y_envelope = [upper_bound, fliplr(lower_bound)];
                        h_envelope = fill(app.CalibrationAxes, x_envelope, y_envelope, [0.7 0.7 0.7], 'FaceAlpha', 0.3, 'EdgeColor', 'none', 'DisplayName', '± 1σ envelope');

                        app.base.project.session.spot.correction.calibration_line.calibration_line_fit = p_avg;

                        % Calculate x-intercepts (roots of the polynomial)
                        x_intercepts = roots([p_avg(1), p_avg(2), p_avg(3)]);

                        % Calculate intercepts for upper and lower envelope bounds
                        % Find where upper_bound = 0 and lower_bound = 0 (approximately using interpolation)
                        % For envelope intercepts, use a more robust approach
                        % For the upper bound
                        if any(upper_bound < 0) && any(upper_bound > 0)
                            % If the curve crosses zero, find where it changes sign
                            upper_cross_idx = find(diff(sign(upper_bound)) ~= 0);
                            if ~isempty(upper_cross_idx)
                                % Linear interpolation between points where sign changes
                                upper_x = interp1(upper_bound(upper_cross_idx:upper_cross_idx+1), x_smooth(upper_cross_idx:upper_cross_idx+1), 0);
                            else
                                upper_x = NaN;
                            end
                        else
                            % If all values are positive or all negative, extrapolate
                            if all(upper_bound > 0)
                                % Extrapolate where y=0 using the linear fit of first points
                                p_upper = polyfit(x_smooth(1:5), upper_bound(1:5), 1);
                                upper_x = -p_upper(2)/p_upper(1);  % x-intercept
                            elseif all(upper_bound < 0)
                                % Extrapolate using last points
                                p_upper = polyfit(x_smooth(end-4:end), upper_bound(end-4:end), 1);
                                upper_x = -p_upper(2)/p_upper(1);  % x-intercept
                            else
                                upper_x = NaN;
                            end
                        end

                        % Same for lower bound
                        if any(lower_bound < 0) && any(lower_bound > 0)
                            lower_cross_idx = find(diff(sign(lower_bound)) ~= 0);
                            if ~isempty(lower_cross_idx)
                                lower_x = interp1(lower_bound(lower_cross_idx:lower_cross_idx+1), x_smooth(lower_cross_idx:lower_cross_idx+1), 0);
                            else
                                lower_x = NaN;
                            end
                        else
                            if all(lower_bound > 0)
                                p_lower = polyfit(x_smooth(1:5), lower_bound(1:5), 1);
                                lower_x = -p_lower(2)/p_lower(1);
                            elseif all(lower_bound < 0)
                                p_lower = polyfit(x_smooth(end-4:end), lower_bound(end-4:end), 1);
                                lower_x = -p_lower(2)/p_lower(1);
                            else
                                lower_x = NaN;
                            end
                        end

                        intercepts_text = sprintf('x-intercepts: %.4f (fit), %.4f (upper), %.4f (lower)', x_intercepts(2), upper_x, lower_x);

                        % For log scale, show original polynomial format
                        quad_formula = sprintf('y = %.8fx² + %.8fx + %.5f, R² = %.4f', p_avg(1), p_avg(2), p_avg(3), ...
                            1 - sum((avg_reference - polyval(p_avg, avg_measured)).^2) / sum((avg_reference - mean(avg_reference)).^2));

                        app.x2EditField.Value = p_avg(1);
                        app.xEditField.Value = p_avg(2);
                        app.cEditField.Value = p_avg(3);

                        % Add text annotation with formulas and x-intercepts
                        text_x = 0.05; % Position in normalized axes coordinates
                        text_y = 0.95;
                        text(app.CalibrationAxes, text_x, text_y, {quad_formula, intercepts_text}, ...
                            'Units', 'normalized', 'FontSize', 9, 'BackgroundColor', [1 1 1 0.7], ...
                            'EdgeColor', 'k', 'Margin', 3);

                        % Add regression lines to legend entries
                        legend_entries{end+1} = h_quad;
                        legend_entries{end+1} = h_envelope;
                    else
                        % Linear fit through averaged points
                        p_avg = polyfit(avg_measured, avg_reference, 1);

                        % Generate points for plotting the fit
                        x_min = min(avg_measured);
                        x_max = max(avg_measured);
                        x_smooth = linspace(x_min, x_max, 500);
                        y_fit = polyval(p_avg, x_smooth);

                        % Plot the fit line
                        h_linear = plot(app.CalibrationAxes, x_smooth, y_fit, '-k', 'LineWidth', 2, 'DisplayName', 'Linear fit (Avg)');

                        % Generate error envelope (± 1 sigma prediction interval)
                        [p_avg, S] = polyfit(avg_measured, avg_reference, 1);
                        [y_pred, delta] = polyval(p_avg, x_smooth, S);
                        upper_bound = y_pred + delta;
                        lower_bound = y_pred - delta;

                        % Plot error envelope
                        x_envelope = [x_smooth, fliplr(x_smooth)];
                        y_envelope = [upper_bound, fliplr(lower_bound)];
                        h_envelope = fill(app.CalibrationAxes, x_envelope, y_envelope, [0.7 0.7 0.7], 'FaceAlpha', 0.3, 'EdgeColor', 'none', 'DisplayName', '± 1σ envelope');

                        app.base.project.session.spot.correction.calibration_line.calibration_line_fit = p_avg;

                        % Calculate x-intercept (where y = 0)
                        x_intercept = -p_avg(2)/p_avg(1);

                        % Calculate intercepts for upper and lower envelope bounds
                        % Find where upper_bound = 0 and lower_bound = 0 (approximately using interpolation)
                        try
                            % For envelope intercepts, use a more robust approach
                            % For the upper bound
                            if any(upper_bound < 0) && any(upper_bound > 0)
                                % If the curve crosses zero, find where it changes sign
                                upper_cross_idx = find(diff(sign(upper_bound)) ~= 0);
                                if ~isempty(upper_cross_idx)
                                    % Linear interpolation between points where sign changes
                                    upper_x = interp1(upper_bound(upper_cross_idx:upper_cross_idx+1), x_smooth(upper_cross_idx:upper_cross_idx+1), 0);
                                else
                                    upper_x = NaN;
                                end
                            else
                                % If all values are positive or all negative, extrapolate
                                if all(upper_bound > 0)
                                    % Extrapolate where y=0 using the linear fit of first points
                                    p_upper = polyfit(x_smooth(1:5), upper_bound(1:5), 1);
                                    upper_x = -p_upper(2)/p_upper(1);  % x-intercept
                                elseif all(upper_bound < 0)
                                    % Extrapolate using last points
                                    p_upper = polyfit(x_smooth(end-4:end), upper_bound(end-4:end), 1);
                                    upper_x = -p_upper(2)/p_upper(1);  % x-intercept
                                else
                                    upper_x = NaN;
                                end
                            end

                            % Same for lower bound
                            if any(lower_bound < 0) && any(lower_bound > 0)
                                lower_cross_idx = find(diff(sign(lower_bound)) ~= 0);
                                if ~isempty(lower_cross_idx)
                                    lower_x = interp1(lower_bound(lower_cross_idx:lower_cross_idx+1), x_smooth(lower_cross_idx:lower_cross_idx+1), 0);
                                else
                                    lower_x = NaN;
                                end
                            else
                                if all(lower_bound > 0)
                                    p_lower = polyfit(x_smooth(1:5), lower_bound(1:5), 1);
                                    lower_x = -p_lower(2)/p_lower(1);
                                elseif all(lower_bound < 0)
                                    p_lower = polyfit(x_smooth(end-4:end), lower_bound(end-4:end), 1);
                                    lower_x = -p_lower(2)/p_lower(1);
                                else
                                    lower_x = NaN;
                                end
                            end

                            intercepts_text = sprintf('x-intercept: %.4f (fit), %.4f (upper), %.4f (lower)', x_intercept, upper_x, lower_x);

                        catch
                            intercepts_text = sprintf('x-intercept: %.4f', x_intercept);
                        end

                        % Linear equation formula
                        r_squared = 1 - sum((avg_reference - polyval(p_avg, avg_measured)).^2) / sum((avg_reference - mean(avg_reference)).^2);
                        linear_formula = sprintf('y = %.8fx + %.5f, R² = %.4f', p_avg(1), p_avg(2), r_squared);

                        app.x2EditField.Value = 0;
                        app.xEditField.Value = double(p_avg(1));
                        app.cEditField.Value = double(p_avg(2));

                        % Add text annotation with formula and x-intercept
                        text_x = 0.05;
                        text_y = 0.95;
                        text(app.CalibrationAxes, text_x, text_y, {linear_formula, intercepts_text}, ...
                            'Units', 'normalized', 'FontSize', 9, 'BackgroundColor', [1 1 1 0.7], ...
                            'EdgeColor', 'k', 'Margin', 3);

                        % Add regression line to legend entries
                        legend_entries{end+1} = h_linear;
                        legend_entries{end+1} = h_envelope;
                    end
                end

                % Set the axis limits based on the data
                if ~isempty(avg_measured) && ~isempty(avg_reference)
                    % Filter out zeros and NaNs for proper limit calculation
                    valid_x = avg_measured(avg_measured > 0 & ~isnan(avg_measured));
                    valid_y = avg_reference(avg_reference > 0 & ~isnan(avg_reference));

                    if ~isempty(valid_x) && ~isempty(valid_y)
                        if app.LogscaleCheckBox.Value
                            % For log scale, use multiplication factors for padding
                            x_min = min(valid_x);
                            x_max = max(valid_x);
                            y_min = min(valid_y);
                            y_max = max(valid_y);

                            % Add some padding (10% on each side in log space)
                            x_range = x_max/x_min;
                            y_range = y_max/y_min;

                            % Set tight limits with padding for log scale
                            xlim(app.CalibrationAxes, [x_min/(x_range^0.1), x_max*(x_range^0.1)]);
                            ylim(app.CalibrationAxes, [y_min/(y_range^0.1), y_max*(y_range^0.1)]);
                        else
                            % For linear scale, use additive padding
                            x_min = min(valid_x);
                            x_max = max(valid_x);
                            y_min = min(valid_y);
                            y_max = max(valid_y);

                            % Account for error bars when determining limits
                            x_min_with_error = min(valid_x - std_measured(avg_measured > 0 & ~isnan(avg_measured)));
                            x_max_with_error = max(valid_x + std_measured(avg_measured > 0 & ~isnan(avg_measured)));

                            % Add some padding (5% on each side in linear space)
                            x_padding = 0.05 * (x_max_with_error - x_min_with_error);
                            y_padding = 0.05 * (y_max - y_min);

                            % Set limits with padding for linear scale
                            % Ensure we don't go below zero for concentration plots
                            xlim(app.CalibrationAxes, [max(0, x_min_with_error - x_padding), x_max_with_error + x_padding]);
                            ylim(app.CalibrationAxes, [max(0, y_min - y_padding), y_max + y_padding]);
                        end
                    end
                end

                % Add 1:1 line
                x_limits = xlim(app.CalibrationAxes);
                y_limits = ylim(app.CalibrationAxes);

                if app.LogscaleCheckBox.Value
                    % Find the common range for log scale
                    min_limit = max([x_limits(1), y_limits(1)]);
                    max_limit = min([x_limits(2), y_limits(2)]);
                    one_one_line = plot(app.CalibrationAxes, [min_limit, max_limit], [min_limit, max_limit], '--', 'Color', [.7 .7 .7], 'LineWidth', 1.5, 'DisplayName', '1:1 Line');
                else
                    % For linear scale
                    min_val = min([x_limits(1), y_limits(1)]);
                    max_val = max([x_limits(2), y_limits(2)]);
                    one_one_line = plot(app.CalibrationAxes, [min_val, max_val], [min_val, max_val], '--', 'Color', [.7 .7 .7], 'LineWidth', 1.5, 'DisplayName', '1:1 Line');
                end

                % Add 1:1 line to legend entries
                legend_entries{end+1} = one_one_line;

                % Add labels and title
                xlabel(app.CalibrationAxes, 'Measured Values');
                ylabel(app.CalibrationAxes, 'True Values');
                title(app.CalibrationAxes, 'Measured vs. True Values');

                % Fix for legend creation
                valid_indices = ~cellfun(@isempty, legend_entries);
                legend(app.CalibrationAxes, [legend_entries{valid_indices}], 'Location', 'southeast');

                if app.LogscaleCheckBox.Value
                    % Set both axes to logarithmic scale
                    set(app.CalibrationAxes, 'XScale', 'log', 'YScale', 'log');
                end

                hold(app.CalibrationAxes, 'off');

                % Add grid for better readability
                grid(app.CalibrationAxes, 'on');

            else
                % For bracket_id 0, combine data from all brackets for selected CAL
                cla(app.CalibrationAxes, 'reset'); % Clear the axes first
                hold(app.CalibrationAxes, 'on');

                % Get logical array of spots to fit
                spots_to_fit = app.base.project.session.spot.correction.calibration_line.spot_to_fit;

                % First filter these to only get QCRM
                qcrm_indices = strcmp(app.base.project.session.spot.type, "QCRM");

                % Combine masks to get only QCRM spots that are selected for fitting
                qcrm_spots_to_fit = spots_to_fit & qcrm_indices;

                % Get reference material names for all spots
                refmat_name = app.base.project.session.spot.referencematerial(qcrm_spots_to_fit);

                % Get unique reference materials for coloring
                unique_refmats = unique(refmat_name);
                colors = lines(length(unique_refmats)); % Generate distinct colors

                % Get the reference materials data and names for true values
                ref_mat_names = app.base.project.session.referencematerials.names;
                ref_mat_data = app.base.project.session.referencematerials.data;

                % Calculate reference Cl values for each reference material
                ref_cl_lookup = containers.Map();
                for i = 1:length(unique_refmats)
                    current_refmat = unique_refmats{i};
                    ref_idx = find(strcmp(ref_mat_names, current_refmat));

                    if ~isempty(ref_idx)
                        ref_table = ref_mat_data{ref_idx};

                        elementName = regexprep(app.AnalyteDropDown.Value, '[0-9]', '');

                        if any(strcmp(ref_table.Properties.VariableNames, elementName))
                            ref_cl_lookup(current_refmat) = ref_table.(elementName);
                        else
                            ref_cl_lookup(current_refmat) = NaN;
                        end
                    else
                        ref_cl_lookup(current_refmat) = NaN;
                    end
                end

                % Initialize containers for averaged values by reference material
                avg_by_refmat = containers.Map();
                std_by_refmat = containers.Map();

                % Initialize each reference material in the map
                for i = 1:length(unique_refmats)
                    current_mat = unique_refmats{i};
                    avg_by_refmat(current_mat) = [];
                    std_by_refmat(current_mat) = [];
                end

                % Process each bracket
                num_brackets = size(mass_fraction_values, 1);
                for bracket_idx = 1:num_brackets
                    if isempty(mass_fraction_values{bracket_idx, app.selected_CAL_for_fit})
                        continue;  % Skip empty brackets
                    end

                    analyte_conc_table = mass_fraction_values{bracket_idx, app.selected_CAL_for_fit};
                    analyte_conc = analyte_conc_table.(app.AnalyteDropDown.Value);

                    measured_analyte_values = analyte_conc(qcrm_spots_to_fit);

                    % Skip if no valid data
                    if isempty(measured_analyte_values)
                        continue;
                    end

                    % For each reference material in this bracket, collect measured values
                    for i = 1:length(unique_refmats)
                        current_mat = unique_refmats{i};
                        mask = strcmp(refmat_name, current_mat);

                        % Skip if no data for this reference material in this bracket
                        if ~any(mask) || all(isnan(measured_analyte_values(mask)))
                            continue;
                        end

                        % Get reference Cl value for this material
                        ref_cl_value = ref_cl_lookup(current_mat);
                        if isnan(ref_cl_value)
                            continue;  % Skip if no reference value
                        end

                        % Filter out zero values
                        valid_measured = measured_analyte_values(mask);
                        valid_data_mask = valid_measured > 0 & ~isnan(valid_measured);

                        if any(valid_data_mask)
                            % Use only valid values
                            valid_values = valid_measured(valid_data_mask);

                            % Add these values to the collection for this reference material
                            avg_by_refmat(current_mat) = [avg_by_refmat(current_mat); valid_values];
                        end
                    end
                end

                % Calculate averages and standard deviations for each reference material
                avg_measured = [];
                avg_reference = [];
                std_measured = [];
                refmat_names_for_avg = {};

                % Create legend entries containers
                legend_handles = [];
                legend_labels = {};

                for i = 1:length(unique_refmats)
                    current_mat = unique_refmats{i};

                    if ~isempty(avg_by_refmat(current_mat)) && isKey(ref_cl_lookup, current_mat) && ~isnan(ref_cl_lookup(current_mat))
                        % Calculate mean and standard deviation
                        mean_val = mean(avg_by_refmat(current_mat));
                        std_val = std(avg_by_refmat(current_mat));

                        % Store for plotting
                        avg_measured = [avg_measured; mean_val];
                        avg_reference = [avg_reference; ref_cl_lookup(current_mat)];
                        std_measured = [std_measured; std_val];
                        refmat_names_for_avg = [refmat_names_for_avg; current_mat];

                        % Plot averaged point with error bars
                        h_avg = errorbar(app.CalibrationAxes, mean_val, ref_cl_lookup(current_mat), 0, 0, std_val, std_val, ...
                            'o', 'Color', colors(i,:), 'MarkerFaceColor', colors(i,:), ...
                            'MarkerEdgeColor', 'k', 'LineWidth', 1.5, 'DisplayName', [current_mat ' (Avg ± σ)']);

                        legend_handles = [legend_handles; h_avg];
                        legend_labels = [legend_labels; [current_mat ' (Avg ± σ)']];
                    end
                end

                % Perform regressions if we have data
                if ~isempty(avg_measured) && ~isempty(avg_reference)
                    if app.QuadraticFitCheckBox.Value
                        % Second-order polynomial regression on averages
                        p_avg = polyfit(avg_measured, avg_reference, 2);

                        % Create a denser array of x-values for smoother curve plotting
                        x_min = min(avg_measured);
                        x_max = max(avg_measured);
                        x_smooth = linspace(x_min, x_max, 500);

                        % Calculate corresponding y values
                        y_fit = polyval(p_avg, x_smooth);

                        % Calculate R-squared using original points
                        r_squared2 = 1 - sum((avg_reference - polyval(p_avg, avg_measured)).^2) / sum((avg_reference - mean(avg_reference)).^2);

                        % Plot regression lines with smoother curve
                        h_quad = plot(app.CalibrationAxes, x_smooth, y_fit, '-k', 'LineWidth', 2, 'DisplayName', 'Quadratic fit (Avg)');

                        app.base.project.session.spot.correction.calibration_line.true_vs_meas_quad_fit = p_avg;

                        % Generate error envelope (± 1 sigma prediction interval)
                        [p_avg, S] = polyfit(avg_measured, avg_reference, 2);
                        [y_pred, delta] = polyval(p_avg, x_smooth, S);
                        upper_bound = y_pred + delta;
                        lower_bound = y_pred - delta;

                        % Plot error envelope
                        x_envelope = [x_smooth, fliplr(x_smooth)];
                        y_envelope = [upper_bound, fliplr(lower_bound)];
                        h_envelope = fill(app.CalibrationAxes, x_envelope, y_envelope, [0.7 0.7 0.7], 'FaceAlpha', 0.3, 'EdgeColor', 'none', 'DisplayName', '± 1σ envelope');

                        % Calculate x-intercepts (roots of the polynomial)
                        x_intercepts = roots([p_avg(1), p_avg(2), p_avg(3)]);

                        % Calculate intercepts for upper and lower envelope bounds
                        % For envelope intercepts, use a more robust approach
                        % For the upper bound
                        if any(upper_bound < 0) && any(upper_bound > 0)
                            % If the curve crosses zero, find where it changes sign
                            upper_cross_idx = find(diff(sign(upper_bound)) ~= 0);
                            if ~isempty(upper_cross_idx)
                                % Linear interpolation between points where sign changes
                                upper_x = interp1(upper_bound(upper_cross_idx:upper_cross_idx+1), x_smooth(upper_cross_idx:upper_cross_idx+1), 0);
                            else
                                upper_x = NaN;
                            end
                        else
                            % If all values are positive or all negative, extrapolate
                            if all(upper_bound > 0)
                                % Extrapolate where y=0 using the linear fit of first points
                                p_upper = polyfit(x_smooth(1:5), upper_bound(1:5), 1);
                                upper_x = -p_upper(2)/p_upper(1);  % x-intercept
                            elseif all(upper_bound < 0)
                                % Extrapolate using last points
                                p_upper = polyfit(x_smooth(end-4:end), upper_bound(end-4:end), 1);
                                upper_x = -p_upper(2)/p_upper(1);  % x-intercept
                            else
                                upper_x = NaN;
                            end
                        end

                        % Same for lower bound
                        if any(lower_bound < 0) && any(lower_bound > 0)
                            lower_cross_idx = find(diff(sign(lower_bound)) ~= 0);
                            if ~isempty(lower_cross_idx)
                                lower_x = interp1(lower_bound(lower_cross_idx:lower_cross_idx+1), x_smooth(lower_cross_idx:lower_cross_idx+1), 0);
                            else
                                lower_x = NaN;
                            end
                        else
                            if all(lower_bound > 0)
                                p_lower = polyfit(x_smooth(1:5), lower_bound(1:5), 1);
                                lower_x = -p_lower(2)/p_lower(1);
                            elseif all(lower_bound < 0)
                                p_lower = polyfit(x_smooth(end-4:end), lower_bound(end-4:end), 1);
                                lower_x = -p_lower(2)/p_lower(1);
                            else
                                lower_x = NaN;
                            end
                        end

                        intercepts_text = sprintf('x-intercepts: %.4f (fit), %.4f (upper), %.4f (lower)', x_intercepts(2), upper_x, lower_x);

                        % For log scale, show original polynomial format
                        quad_formula = sprintf('y = %.8fx² + %.8fx + %.4f, R² = %.4f', p_avg(1), p_avg(2), p_avg(3), r_squared2);

                        app.x2EditField.Value = double(p_avg(1));
                        app.xEditField.Value = double(p_avg(2));
                        app.cEditField.Value = double(p_avg(3));

                        % Add text annotation with formulas
                        text_x = 0.05; % Position in normalized axes coordinates
                        text_y = 0.95;
                        text(app.CalibrationAxes, text_x, text_y, {quad_formula, intercepts_text}, ...
                            'Units', 'normalized', 'FontSize', 9, 'BackgroundColor', [1 1 1 0.7], ...
                            'EdgeColor', 'k', 'Margin', 3);

                        % Add regression lines to legend entries
                        legend_handles = [legend_handles; h_quad; h_envelope];
                        legend_labels = [legend_labels; 'Quadratic fit (Avg)'; '± 1σ envelope'];
                    else
                        % Linear regression on averages
                        p_avg = polyfit(avg_measured, avg_reference, 1);

                        % Create a denser array of x-values for smoother line
                        x_min = min(avg_measured);
                        x_max = max(avg_measured);
                        x_smooth = linspace(x_min, x_max, 500);

                        % Calculate corresponding y values
                        y_fit = polyval(p_avg, x_smooth);

                        % Calculate R-squared
                        r_squared = 1 - sum((avg_reference - polyval(p_avg, avg_measured)).^2) / sum((avg_reference - mean(avg_reference)).^2);

                        % Plot linear regression line
                        h_linear = plot(app.CalibrationAxes, x_smooth, y_fit, '-k', 'LineWidth', 2, 'DisplayName', 'Linear fit (Avg)');

                        app.base.project.session.spot.correction.calibration_line.true_vs_meas_linear_fit = p_avg;

                        % Generate error envelope (± 1 sigma prediction interval)
                        [p_avg, S] = polyfit(avg_measured, avg_reference, 1);
                        [y_pred, delta] = polyval(p_avg, x_smooth, S);
                        upper_bound = y_pred + delta;
                        lower_bound = y_pred - delta;

                        % Plot error envelope
                        x_envelope = [x_smooth, fliplr(x_smooth)];
                        y_envelope = [upper_bound, fliplr(lower_bound)];
                        h_envelope = fill(app.CalibrationAxes, x_envelope, y_envelope, [0.7 0.7 0.7], 'FaceAlpha', 0.3, 'EdgeColor', 'none', 'DisplayName', '± 1σ envelope');

                        % Calculate x-intercept (where y = 0)
                        x_intercept = -p_avg(2)/p_avg(1);

                        % Calculate intercepts for upper and lower envelope bounds
                        try
                            % For envelope intercepts, use a more robust approach
                            % For the upper bound
                            if any(upper_bound < 0) && any(upper_bound > 0)
                                % If the curve crosses zero, find where it changes sign
                                upper_cross_idx = find(diff(sign(upper_bound)) ~= 0);
                                if ~isempty(upper_cross_idx)
                                    % Linear interpolation between points where sign changes
                                    upper_x = interp1(upper_bound(upper_cross_idx:upper_cross_idx+1), x_smooth(upper_cross_idx:upper_cross_idx+1), 0);
                                else
                                    upper_x = NaN;
                                end
                            else
                                % If all values are positive or all negative, extrapolate
                                if all(upper_bound > 0)
                                    % Extrapolate where y=0 using the linear fit of first points
                                    p_upper = polyfit(x_smooth(1:5), upper_bound(1:5), 1);
                                    upper_x = -p_upper(2)/p_upper(1);  % x-intercept
                                elseif all(upper_bound < 0)
                                    % Extrapolate using last points
                                    p_upper = polyfit(x_smooth(end-4:end), upper_bound(end-4:end), 1);
                                    upper_x = -p_upper(2)/p_upper(1);  % x-intercept
                                else
                                    upper_x = NaN;
                                end
                            end

                            % Same for lower bound
                            if any(lower_bound < 0) && any(lower_bound > 0)
                                lower_cross_idx = find(diff(sign(lower_bound)) ~= 0);
                                if ~isempty(lower_cross_idx)
                                    lower_x = interp1(lower_bound(lower_cross_idx:lower_cross_idx+1), x_smooth(lower_cross_idx:lower_cross_idx+1), 0);
                                else
                                    lower_x = NaN;
                                end
                            else
                                if all(lower_bound > 0)
                                    p_lower = polyfit(x_smooth(1:5), lower_bound(1:5), 1);
                                    lower_x = -p_lower(2)/p_lower(1);
                                elseif all(lower_bound < 0)
                                    p_lower = polyfit(x_smooth(end-4:end), lower_bound(end-4:end), 1);
                                    lower_x = -p_lower(2)/p_lower(1);
                                else
                                    lower_x = NaN;
                                end
                            end

                            intercepts_text = sprintf('x-intercept: %.4f (fit), %.4f (upper), %.4f (lower)', x_intercept, upper_x, lower_x);

                        catch
                            intercepts_text = sprintf('x-intercept: %.4f', x_intercept);
                        end

                        % Linear equation formula
                        linear_formula = sprintf('y = %.8fx + %.4f, R² = %.4f', p_avg(1), p_avg(2), r_squared);

                        app.x2EditField.Value = 0;
                        app.xEditField.Value = double(p_avg(1));
                        app.cEditField.Value = double(p_avg(2));

                        % Add text annotation with formula
                        text_x = 0.05;
                        text_y = 0.95;
                        text(app.CalibrationAxes, text_x, text_y, {linear_formula, intercepts_text}, ...
                            'Units', 'normalized', 'FontSize', 9, 'BackgroundColor', [1 1 1 0.7], ...
                            'EdgeColor', 'k', 'Margin', 3);

                        % Add regression line to legend entries
                        legend_handles = [legend_handles; h_linear; h_envelope];
                        legend_labels = [legend_labels; 'Linear fit (Avg)'; '± 1σ envelope'];
                    end
                end

                % Set axis limits based on data points
                if ~isempty(avg_measured) && ~isempty(avg_reference)
                    % Filter out zeros and NaNs for proper limit calculation
                    valid_measured = avg_measured(avg_measured > 0 & ~isnan(avg_measured));
                    valid_reference = avg_reference(avg_reference > 0 & ~isnan(avg_reference));

                    if ~isempty(valid_measured) && ~isempty(valid_reference)
                        if app.LogscaleCheckBox.Value
                            % For log scale, use multiplication factors for padding
                            x_min = min(valid_measured);
                            x_max = max(valid_measured);
                            y_min = min(valid_reference);
                            y_max = max(valid_reference);

                            % Add some padding (10% on each side in log space)
                            x_range = x_max/x_min;
                            y_range = y_max/y_min;

                            % Set tight limits with padding for log scale
                            xlim(app.CalibrationAxes, [x_min/(x_range^0.1), x_max*(x_range^0.1)]);
                            ylim(app.CalibrationAxes, [y_min/(y_range^0.1), y_max*(y_range^0.1)]);
                        else
                            % For linear scale, use additive padding
                            x_min = min(valid_measured);
                            x_max = max(valid_measured);
                            y_min = min(valid_reference);
                            y_max = max(valid_reference);

                            % Account for error bars when determining limits
                            x_min_with_error = min(valid_measured - std_measured(avg_measured > 0 & ~isnan(avg_measured)));
                            x_max_with_error = max(valid_measured + std_measured(avg_measured > 0 & ~isnan(avg_measured)));

                            % Add some padding (5% on each side in linear space)
                            x_padding = 0.05 * (x_max_with_error - x_min_with_error);
                            y_padding = 0.05 * (y_max - y_min);

                            % Set limits with padding for linear scale
                            % Ensure we don't go below zero for concentration plots
                            xlim(app.CalibrationAxes, [max(0, x_min_with_error - x_padding), x_max_with_error + x_padding]);
                            ylim(app.CalibrationAxes, [max(0, y_min - y_padding), y_max + y_padding]);
                        end
                    end
                end

                % Add 1:1 line based on scaling
                x_limits = xlim(app.CalibrationAxes);
                y_limits = ylim(app.CalibrationAxes);

                if app.LogscaleCheckBox.Value
                    % Get the current axis limits after they've been set
                    min_limit = max([x_limits(1), y_limits(1)]);
                    max_limit = min([x_limits(2), y_limits(2)]);

                    % Draw the 1:1 line within these limits for log scale
                    one_one_line = plot(app.CalibrationAxes, [min_limit, max_limit], [min_limit, max_limit], '--', 'Color', [.7 .7 .7], 'LineWidth', 1.5, 'DisplayName', '1:1 Line');
                else
                    % For linear scale
                    min_val = min([x_limits(1), y_limits(1)]);
                    max_val = max([x_limits(2), y_limits(2)]);

                    % Draw the 1:1 line
                    one_one_line = plot(app.CalibrationAxes, [min_val, max_val], [min_val, max_val], '--', 'Color', [.7 .7 .7], 'LineWidth', 1.5, 'DisplayName', '1:1 Line');


                    % Add 1:1 line to legend
                    legend_handles = [legend_handles; one_one_line];
                    legend_labels = [legend_labels; '1:1 Line'];

                    % Add labels and title
                    xlabel(app.CalibrationAxes, 'Measured Values');
                    ylabel(app.CalibrationAxes, 'Reference Values');
                    title(app.CalibrationAxes, 'Measured vs. Reference Values (All Brackets)');

                    % Create legend
                    legend(app.CalibrationAxes, legend_handles, legend_labels, 'Location', 'southeast');

                    if app.LogscaleCheckBox.Value
                        % Set both axes to logarithmic scale
                        set(app.CalibrationAxes, 'XScale', 'log', 'YScale', 'log');
                    end

                    % Add grid for better readability
                    grid(app.CalibrationAxes, 'on');

                    hold(app.CalibrationAxes, 'off');
                end
            end
        end


        % not avearge and without error envelope
        % function update_calibration_plot(app)
        %
        %
        %     %%%%%%%%%%%%%% get the measured values
        %     % then get the measured or corrected Cl value
        %     if isequal(app.ISappliedDropDown.Value, 'Yes')
        %         % Get matrix composition data
        %         mass_fraction_values = app.base.project.session.spot.matrix_composition;
        %
        %     elseif isequal(app.ISappliedDropDown.Value, 'No')
        %         % Get the sensitivity tables
        %         sensitivity = app.base.project.session.spot.sensitivities;
        %
        %         % Create a copy of sensitivity structure to store preliminary mass fractions
        %         mass_fraction_values = sensitivity;
        %
        %         % Get Cl35 intensities
        %         intensity = app.base.project.session.spot.bkgd_corrected_cps.(app.AnalyteDropDown.Value);
        %
        %         % Loop through each bracket and calculate preliminary mass fractions
        %         for i = 1:size(sensitivity, 1)
        %             for j = 1:size(sensitivity, 2)
        %                 % For each bracket and CAL, keep the same table structure
        %                 % Only divide where sensitivity is not zero
        %                 nonzero_indices = sensitivity{i,j}.(app.AnalyteDropDown.Value) ~= 0;
        %
        %                 % Initialize with the same structure (keep zeros as zeros)
        %                 mass_fraction_values{i,j}.(app.AnalyteDropDown.Value) = sensitivity{i,j}.(app.AnalyteDropDown.Value);
        %
        %                 % Only calculate mass fractions for non-zero sensitivity values
        %                 if any(nonzero_indices)
        %                     % Extract corresponding intensities for non-zero sensitivity spots
        %                     relevant_intensities = intensity(nonzero_indices);
        %                     relevant_sensitivities = sensitivity{i,j}.(app.AnalyteDropDown.Value)(nonzero_indices);
        %
        %                     % Calculate preliminary mass fraction
        %                     mass_fraction_values{i,j}.(app.AnalyteDropDown.Value)(nonzero_indices) = relevant_intensities ./ relevant_sensitivities;
        %                 end
        %             end
        %         end
        %     end
        %
        %     % Get Cl35 values from matrix_comp table
        %     bracket_id = app.bracket_idDropDown.Value;
        %
        %
        %     % Extract Cl35 values based on bracket_id
        %     if ~isequal(bracket_id, 0)
        %         % For specific bracket, extract just that table
        %         analyte_conc_table = mass_fraction_values{bracket_id, app.selected_CAL_for_fit};
        %         analyte_conc = analyte_conc_table.(app.AnalyteDropDown.Value);
        %
        %         %%%%%%%%%%%%%%%%%%%%%
        %         % Get logical array of spots to fit
        %         spots_to_fit = app.base.project.session.spot.correction.calibration_line.spot_to_fit;
        %
        %         % First filter these to only get QCRM
        %         qcrm_indices = strcmp(app.base.project.session.spot.type, "QCRM");
        %
        %         % Combine masks to get only QCRM spots that are selected for fitting
        %         qcrm_spots_to_fit = spots_to_fit & qcrm_indices;
        %
        %         measured_analyte_values = analyte_conc(qcrm_spots_to_fit);
        %
        %         refmat_name = app.base.project.session.spot.referencematerial(qcrm_spots_to_fit);
        %
        %         %%%% get the true values
        %         % Initialize storage for Cl reference values
        %         ref_cl_values = zeros(size(refmat_name));
        %
        %         % Get the reference materials data and names
        %         ref_mat_names = app.base.project.session.referencematerials.names;
        %         ref_mat_data = app.base.project.session.referencematerials.data;
        %
        %         % Loop through each reference material in refmat_name
        %         for i = 1:length(refmat_name)
        %             current_refmat = refmat_name(i);
        %
        %             % Find the index of this reference material in the reference materials list
        %             ref_idx = find(strcmp(ref_mat_names, current_refmat));
        %
        %             if ~isempty(ref_idx)
        %                 % Get the reference data table for this material
        %                 ref_table = ref_mat_data{ref_idx};
        %
        %                 elementName = regexprep(app.AnalyteDropDown.Value, '[0-9]', '');
        %
        %                 % Check if Cl column exists in the table
        %                 if any(strcmp(ref_table.Properties.VariableNames, elementName))
        %                     % Get the Cl value
        %                     ref_cl_values(i) = ref_table.(elementName);
        %                 else
        %                     % If Cl is not in the table, mark as NaN
        %                     ref_cl_values(i) = NaN;
        %
        %                 end
        %             else
        %                 % Reference material not found in database
        %                 ref_cl_values(i) = NaN;
        %
        %             end
        %         end
        %
        %         %%%%
        %
        %         % Plot measured vs true values
        %         cla(app.CalibrationAxes, 'reset'); % Clear the axes first
        %
        %         % Get unique reference materials for coloring
        %         unique_refmats = unique(refmat_name);
        %         colors = lines(length(unique_refmats)); % Generate distinct colors
        %
        %         % Initialize legend entries
        %         legend_entries = cell(length(unique_refmats), 1);
        %
        %         hold(app.CalibrationAxes, 'on');
        %
        %
        %         % Collect all valid data points for regression
        %         all_x = [];
        %         all_y = [];
        %
        %         % Plot each reference material with a different color
        %         for i = 1:length(unique_refmats)
        %             current_mat = unique_refmats{i};
        %             mask = strcmp(refmat_name, current_mat);
        %
        %             % Filter out zero values from measured_cl_values
        %             valid_data_mask = mask & (measured_analyte_values > 0);
        %
        %             % Only plot if there are valid data points
        %             if any(valid_data_mask) && any(~isnan(ref_cl_values(valid_data_mask)))
        %                 h = scatter(app.CalibrationAxes, measured_analyte_values(valid_data_mask), ref_cl_values(valid_data_mask), ...
        %                     50, colors(i,:), 'filled', 'MarkerEdgeColor', 'k', 'DisplayName', current_mat);
        %
        %                 legend_entries{i} = h;
        %
        %                 % Collect data for regression
        %                 all_x = [all_x; measured_analyte_values(valid_data_mask)];
        %                 all_y = [all_y; ref_cl_values(valid_data_mask)];
        %             end
        %         end
        %
        %         if ~isempty(all_x) && ~isempty(all_y)
        %             if app.QuadraticFitCheckBox.Value
        %                 % Second-order polynomial regression
        %                 p = polyfit(all_x, all_y, 2);
        %
        %                 % Create a denser array of x-values for smoother curve plotting
        %                 x_min = min(all_x);
        %                 x_max = max(all_x);
        %                 x_smooth = linspace(x_min, x_max, 500); % 500 points for smooth curve
        %
        %                 % Calculate corresponding y values
        %                 y_fit = polyval(p, x_smooth);
        %
        %                 % Calculate R-squared using original points
        %                 r_squared2 = 1 - sum((all_y - polyval(p, all_x)).^2) / sum((all_y - mean(all_y)).^2);
        %
        %                 % Plot regression lines with smoother curve
        %                 h_quad = plot(app.CalibrationAxes, x_smooth, y_fit, '-', 'Color', 'k', 'LineWidth', 1, 'DisplayName', 'Quadratic fit');
        %
        %                 app.base.project.session.spot.correction.calibration_line.calibration_line_fit = p;
        %
        %                 % Calculate x-intercepts (roots of the polynomial)
        %                 x_intercepts = roots([p(1), p(2), p(3)]);
        %                 intercepts_text = sprintf('x-intercepts: %.4f', x_intercepts(2));
        %
        %                 % For log scale, show original polynomial format
        %                 quad_formula = sprintf('y = %.8fx² + %.8fx + %.5f, R² = %.4f', p(1), p(2), p(3), r_squared2);
        %
        %                 app.x2EditField.Value = p(1);
        %                 app.xEditField.Value = p(2);
        %                 app.cEditField.Value = p(3);
        %
        %                 % Add text annotation with formulas
        %                 text_x = 0.05; % Position in normalized axes coordinates
        %                 text_y = 0.95;
        %                 % Add text annotation with formulas and x-intercepts
        %                 text(app.CalibrationAxes, text_x, text_y, {quad_formula, intercepts_text}, ...
        %                     'Units', 'normalized', 'FontSize', 9, 'BackgroundColor', [1 1 1 0.7], ...
        %                     'EdgeColor', 'k', 'Margin', 3);
        %
        %                 % Add regression lines to legend entries
        %                 legend_entries{end+1} = h_quad;
        %                 unique_refmats{end+1} = 'Quadratic fit';
        %             else
        %                 % Linear regression (first-order polynomial)
        %                 p = polyfit(all_x, all_y, 1);
        %
        %                 % Create a denser array of x-values for smoother line
        %                 x_min = min(all_x);
        %                 x_max = max(all_x);
        %                 x_smooth = linspace(x_min, x_max, 500);
        %
        %                 % Calculate corresponding y values
        %                 y_fit = polyval(p, x_smooth);
        %
        %                 % Calculate R-squared using original points
        %                 r_squared = 1 - sum((all_y - polyval(p, all_x)).^2) / sum((all_y - mean(all_y)).^2);
        %
        %                 % Plot linear regression line
        %                 h_linear = plot(app.CalibrationAxes, x_smooth, y_fit, '-', 'Color', 'b', 'LineWidth', 1, 'DisplayName', 'Linear fit');
        %
        %                 app.base.project.session.spot.correction.calibration_line.calibration_line_fit = p;
        %
        %                 % Calculate x-intercept (where y = 0)
        %                 x_intercept = -p(2)/p(1);
        %                 intercepts_text = sprintf('x-intercept: %.4f', x_intercept);
        %
        %                 % Linear equation formula
        %                 linear_formula = sprintf('y = %.8fx + %.5f, R² = %.4f', p(1), p(2), r_squared);
        %
        %                 app.x2EditField.Value = 0;
        %                 app.xEditField.Value = p(1);
        %                 app.cEditField.Value = p(2);
        %
        %                 % Add text annotation with formula and x-intercept
        %                 text_x = 0.05;
        %                 text_y = 0.95;
        %                 text(app.CalibrationAxes, text_x, text_y, {linear_formula, intercepts_text}, ...
        %                     'Units', 'normalized', 'FontSize', 9, 'BackgroundColor', [1 1 1 0.7], ...
        %                     'EdgeColor', 'k', 'Margin', 3);
        %
        %                 % Add regression line to legend entries
        %                 legend_entries{end+1} = h_linear;
        %                 unique_refmats{end+1} = 'Linear fit';
        %             end
        %         end
        %
        %         % After plotting all the data points and regression lines, but before adding the 1:1 line:
        %
        %         % Get current data limits for valid data points
        %         if ~isempty(all_x) && ~isempty(all_y)
        %             % Filter out zeros and NaNs for proper limit calculation
        %             valid_x = all_x(all_x > 0 & ~isnan(all_x));
        %             valid_y = all_y(all_y > 0 & ~isnan(all_y));
        %
        %             if ~isempty(valid_x) && ~isempty(valid_y)
        %                 if app.LogscaleCheckBox.Value
        %                     % For log scale, use multiplication factors for padding
        %                     x_min = min(valid_x);
        %                     x_max = max(valid_x);
        %                     y_min = min(valid_y);
        %                     y_max = max(valid_y);
        %
        %                     % Add some padding (10% on each side in log space)
        %                     x_range = x_max/x_min;
        %                     y_range = y_max/y_min;
        %
        %                     % Set tight limits with padding for log scale
        %                     xlim(app.CalibrationAxes, [x_min/(x_range^0.1), x_max*(x_range^0.1)]);
        %                     ylim(app.CalibrationAxes, [y_min/(y_range^0.1), y_max*(y_range^0.1)]);
        %                 else
        %                     % For linear scale, use additive padding
        %                     x_min = min(valid_x);
        %                     x_max = max(valid_x);
        %                     y_min = min(valid_y);
        %                     y_max = max(valid_y);
        %
        %                     % Add some padding (5% on each side in linear space)
        %                     x_padding = 0.05 * (x_max - x_min);
        %                     y_padding = 0.05 * (y_max - y_min);
        %
        %                     % Set limits with padding for linear scale
        %                     % Ensure we don't go below zero for concentration plots
        %                     xlim(app.CalibrationAxes, [max(0, x_min - x_padding), x_max + x_padding]);
        %                     ylim(app.CalibrationAxes, [max(0, y_min - y_padding), y_max + y_padding]);
        %                 end
        %             end
        %         end
        %
        %         % Then modify how the 1:1 line is created based on the axis scaling
        %         if app.LogscaleCheckBox.Value
        %             % Get the current axis limits after they've been set
        %             x_limits = xlim(app.CalibrationAxes);
        %             y_limits = ylim(app.CalibrationAxes);
        %
        %             % Find the common range to ensure the 1:1 line properly covers the plot area
        %             min_limit = max([x_limits(1), y_limits(1)]);
        %             max_limit = min([x_limits(2), y_limits(2)]);
        %
        %             % Draw the 1:1 line within these limits for log scale
        %             one_one_line = plot(app.CalibrationAxes, [min_limit, max_limit], [min_limit, max_limit], '--', 'Color', [.7 .7 .7], 'DisplayName', '1:1 Line');
        %         else
        %             % For linear scale, extend the 1:1 line slightly beyond the axis limits
        %             x_limits = xlim(app.CalibrationAxes);
        %             y_limits = ylim(app.CalibrationAxes);
        %
        %             % Find the max extent needed for the line
        %             min_val = min([x_limits(1), y_limits(1)]);
        %             max_val = max([x_limits(2), y_limits(2)]);
        %
        %             % Draw the 1:1 line
        %             one_one_line = plot(app.CalibrationAxes, [min_val, max_val], [min_val, max_val], '--', 'Color', [.7 .7 .7], 'DisplayName', '1:1 Line');
        %         end
        %         % Add labels and title
        %         xlabel(app.CalibrationAxes, 'Measured Values');
        %         ylabel(app.CalibrationAxes, 'True Values');
        %         title(app.CalibrationAxes, 'Measured vs. True Values');
        %
        %         % Fix for legend creation
        %         valid_indices = ~cellfun(@isempty, legend_entries);
        %         valid_handles = legend_entries(valid_indices);
        %         valid_names = unique_refmats(valid_indices);
        %
        %         % Create complete legend entries
        %         all_handles = [valid_handles; {one_one_line}];
        %         all_names = [valid_names; {'1:1 Line'}];
        %
        %         % Use legend with separate arguments
        %         legend(app.CalibrationAxes, [all_handles{:}], all_names, 'Location', 'southeast');
        %
        %         if app.LogscaleCheckBox.Value
        %             % Set both axes to logarithmic scale
        %             set(app.CalibrationAxes, 'XScale', 'log', 'YScale', 'log');
        %         end
        %
        %         hold(app.CalibrationAxes, 'off');
        %
        %         % Add grid for better readability
        %         grid(app.CalibrationAxes, 'on');
        %
        %     else
        %
        %         % For bracket_id 0, combine data from all brackets for selected CAL
        %         cla(app.CalibrationAxes, 'reset'); % Clear the axes first
        %         hold(app.CalibrationAxes, 'on');
        %
        %         % Get logical array of spots to fit
        %         spots_to_fit = app.base.project.session.spot.correction.calibration_line.spot_to_fit;
        %
        %         % First filter these to only get QCRM
        %         qcrm_indices = strcmp(app.base.project.session.spot.type, "QCRM");
        %
        %         % Combine masks to get only QCRM spots that are selected for fitting
        %         qcrm_spots_to_fit = spots_to_fit & qcrm_indices;
        %
        %         % Get reference material names for all spots
        %         refmat_name = app.base.project.session.spot.referencematerial(qcrm_spots_to_fit);
        %
        %         % Get unique reference materials for coloring
        %         unique_refmats = unique(refmat_name);
        %         colors = lines(length(unique_refmats)); % Generate distinct colors
        %
        %         % Get the reference materials data and names for true values
        %         ref_mat_names = app.base.project.session.referencematerials.names;
        %         ref_mat_data = app.base.project.session.referencematerials.data;
        %
        %         % Calculate reference Cl values for each reference material
        %         ref_cl_lookup = containers.Map();
        %         for i = 1:length(unique_refmats)
        %             current_refmat = unique_refmats{i};
        %             ref_idx = find(strcmp(ref_mat_names, current_refmat));
        %
        %             if ~isempty(ref_idx)
        %                 ref_table = ref_mat_data{ref_idx};
        %
        %                 elementName = regexprep(app.AnalyteDropDown.Value, '[0-9]', '');
        %
        %                 if any(strcmp(ref_table.Properties.VariableNames, elementName))
        %                     ref_cl_lookup(current_refmat) = ref_table.(elementName);
        %                 else
        %                     ref_cl_lookup(current_refmat) = NaN;
        %                 end
        %             else
        %                 ref_cl_lookup(current_refmat) = NaN;
        %             end
        %         end
        %
        %         % Define marker styles for different brackets
        %         marker_styles = {'o', 's', '^', 'd', 'v', '>', '<', 'p', 'h', '*'};
        %         num_brackets = size(mass_fraction_values, 1);
        %
        %         % Create legend entries containers
        %         legend_handles = [];
        %         legend_labels = {};
        %
        %         % Collect all data points for later axis limit adjustment
        %         all_measured_values = [];
        %         all_reference_values = [];
        %
        %         % Process each bracket
        %         for bracket_idx = 1:num_brackets
        %             if isempty(mass_fraction_values{bracket_idx, app.selected_CAL_for_fit})
        %                 continue;  % Skip empty brackets
        %             end
        %
        %             analyte_conc_table = mass_fraction_values{bracket_idx, app.selected_CAL_for_fit};
        %             analyte_conc = analyte_conc_table.(app.AnalyteDropDown.Value);
        %
        %             measured_analyte_values = analyte_conc(qcrm_spots_to_fit);
        %
        %             % Skip if no valid data
        %             if isempty(measured_analyte_values)
        %                 continue;
        %             end
        %
        %             % Get marker style for this bracket (cycle if needed)
        %             marker_style = marker_styles{mod(bracket_idx-1, length(marker_styles))+1};
        %
        %             % Plot each reference material with its color but using the bracket's marker
        %             for i = 1:length(unique_refmats)
        %                 current_mat = unique_refmats{i};
        %                 mask = strcmp(refmat_name, current_mat);
        %
        %                 % Skip if no data for this reference material in this bracket
        %                 if ~any(mask) || all(isnan(measured_analyte_values(mask)))
        %                     continue;
        %                 end
        %
        %                 % Get reference Cl value for this material
        %                 ref_cl_value = ref_cl_lookup(current_mat);
        %                 if isnan(ref_cl_value)
        %                     continue;  % Skip if no reference value
        %                 end
        %
        %                 % Create true values array (same reference value for all spots of this material)
        %                 ref_cl_values = repmat(ref_cl_value, sum(mask), 1);
        %
        %                 % Filter out zero values
        %                 valid_data_mask = measured_analyte_values(mask) > 0;
        %                 if ~any(valid_data_mask)
        %                     continue;  % Skip if all values are zero
        %                 end
        %
        %                 % Use only non-zero values
        %                 valid_measured = measured_analyte_values(mask);
        %                 valid_measured = valid_measured(valid_data_mask);
        %                 valid_ref = ref_cl_values(valid_data_mask);
        %
        %                 % Collect data for axis limits (only non-zero values)
        %                 all_measured_values = [all_measured_values; valid_measured];
        %                 all_reference_values = [all_reference_values; valid_ref];
        %
        %                 % Plot with material color and bracket marker (only non-zero values)
        %                 h = scatter(app.CalibrationAxes, valid_measured, valid_ref, ...
        %                     50, colors(i,:), marker_style, 'filled', 'MarkerEdgeColor', 'k', ...
        %                     'DisplayName', sprintf('%s (Bracket %d)', current_mat, bracket_idx));
        %
        %                 % Add to legend entries
        %                 legend_handles = [legend_handles; h];
        %                 legend_labels = [legend_labels; sprintf('%s (Bracket %d)', current_mat, bracket_idx)];
        %
        %
        %             end
        %         end
        %
        %         % Perform regressions if we have data
        %         if ~isempty(all_measured_values) && ~isempty(all_reference_values)
        %             if app.QuadraticFitCheckBox.Value
        %                 % Second-order polynomial regression
        %                 p = polyfit(all_measured_values, all_reference_values, 2);
        %
        %                 % Create a denser array of x-values for smoother curve plotting
        %                 x_min = min(all_measured_values);
        %                 x_max = max(all_measured_values);
        %                 x_smooth = linspace(x_min, x_max, 500);
        %
        %                 % Calculate corresponding y values
        %                 y_fit = polyval(p, x_smooth);
        %
        %                 % Calculate R-squared using original points
        %                 r_squared2 = 1 - sum((all_reference_values - polyval(p, all_measured_values)).^2) / sum((all_reference_values - mean(all_reference_values)).^2);
        %
        %                 % Plot regression lines with smoother curve
        %                 h_quad = plot(app.CalibrationAxes, x_smooth, y_fit, '-k', 'LineWidth', 1, 'DisplayName', 'Quadratic fit');
        %
        %                 app.base.project.session.spot.correction.calibration_line.true_vs_meas_quad_fit = p;
        %
        %                 % Calculate x-intercepts (roots of the polynomial)
        %                 x_intercepts = roots([p(1), p(2), p(3)]);
        %                 intercepts_text = sprintf('x-intercepts: %.4f', x_intercepts(2));
        %
        %                 % For log scale, show original polynomial format
        %                 quad_formula = sprintf('y = %.8fx² + %.8fx + %.4f, R² = %.4f', p(1), p(2), p(3), r_squared2);
        %
        %                 app.x2EditField.Value = p(1);
        %                 app.xEditField.Value = p(2);
        %                 app.cEditField.Value = p(3);
        %
        %                 % Add text annotation with formulas
        %                 text_x = 0.05; % Position in normalized axes coordinates
        %                 text_y = 0.95;
        %                 text(app.CalibrationAxes, text_x, text_y, {quad_formula, intercepts_text}, ...
        %                     'Units', 'normalized', 'FontSize', 9, 'BackgroundColor', [1 1 1 0.7], ...
        %                     'EdgeColor', 'k', 'Margin', 3);
        %
        %                 % Add regression lines to legend entries
        %                 legend_handles = [legend_handles; h_quad];
        %                 legend_labels = [legend_labels; 'Quadratic fit'];
        %             else
        %                 % Linear regression
        %                 p = polyfit(all_measured_values, all_reference_values, 1);
        %
        %                 % Create a denser array of x-values for smoother line
        %                 x_min = min(all_measured_values);
        %                 x_max = max(all_measured_values);
        %                 x_smooth = linspace(x_min, x_max, 500);
        %
        %                 % Calculate corresponding y values
        %                 y_fit = polyval(p, x_smooth);
        %
        %                 % Calculate R-squared
        %                 r_squared = 1 - sum((all_reference_values - polyval(p, all_measured_values)).^2) / sum((all_reference_values - mean(all_reference_values)).^2);
        %
        %                 % Plot linear regression line
        %                 h_linear = plot(app.CalibrationAxes, x_smooth, y_fit, '-b', 'LineWidth', 1, 'DisplayName', 'Linear fit');
        %
        %                 app.base.project.session.spot.correction.calibration_line.true_vs_meas_linear_fit = p;
        %
        %                 % Calculate x-intercept
        %                 x_intercept = -p(2)/p(1);
        %                 intercepts_text = sprintf('x-intercept: %.4f', x_intercept);
        %
        %                 % Linear equation formula
        %                 linear_formula = sprintf('y = %.8fx + %.4f, R² = %.4f', p(1), p(2), r_squared);
        %
        %                 app.x2EditField.Value = 0;
        %                 app.xEditField.Value = p(1);
        %                 app.cEditField.Value = p(2);
        %
        %                 % Add text annotation with formula
        %                 text_x = 0.05;
        %                 text_y = 0.95;
        %                 text(app.CalibrationAxes, text_x, text_y, {linear_formula, intercepts_text}, ...
        %                     'Units', 'normalized', 'FontSize', 9, 'BackgroundColor', [1 1 1 0.7], ...
        %                     'EdgeColor', 'k', 'Margin', 3);
        %
        %                 % Add regression line to legend entries
        %                 legend_handles = [legend_handles; h_linear];
        %                 legend_labels = [legend_labels; 'Linear fit'];
        %             end
        %         end
        %
        %         % After collecting all data points but before adding the 1:1 line:
        %
        %         % Get current data limits for valid data points
        %         if ~isempty(all_measured_values) && ~isempty(all_reference_values)
        %             % Filter out zeros and NaNs for proper limit calculation
        %             valid_measured = all_measured_values(all_measured_values > 0 & ~isnan(all_measured_values));
        %             valid_reference = all_reference_values(all_reference_values > 0 & ~isnan(all_reference_values));
        %
        %             if ~isempty(valid_measured) && ~isempty(valid_reference)
        %                 if app.LogscaleCheckBox.Value
        %                     % For log scale, use multiplication factors for padding
        %                     x_min = min(valid_measured);
        %                     x_max = max(valid_measured);
        %                     y_min = min(valid_reference);
        %                     y_max = max(valid_reference);
        %
        %                     % Add some padding (10% on each side in log space)
        %                     x_range = x_max/x_min;
        %                     y_range = y_max/y_min;
        %
        %                     % Set tight limits with padding for log scale
        %                     xlim(app.CalibrationAxes, [x_min/(x_range^0.1), x_max*(x_range^0.1)]);
        %                     ylim(app.CalibrationAxes, [y_min/(y_range^0.1), y_max*(y_range^0.1)]);
        %                 else
        %                     % For linear scale, use additive padding
        %                     x_min = min(valid_measured);
        %                     x_max = max(valid_measured);
        %                     y_min = min(valid_reference);
        %                     y_max = max(valid_reference);
        %
        %                     % Add some padding (5% on each side in linear space)
        %                     x_padding = 0.05 * (x_max - x_min);
        %                     y_padding = 0.05 * (y_max - y_min);
        %
        %                     % Set limits with padding for linear scale
        %                     % Ensure we don't go below zero for concentration plots
        %                     xlim(app.CalibrationAxes, [max(0, x_min - x_padding), x_max + x_padding]);
        %                     ylim(app.CalibrationAxes, [max(0, y_min - y_padding), y_max + y_padding]);
        %                 end
        %             end
        %         end
        %
        %         % Then modify the 1:1 line based on scaling
        %         if app.LogscaleCheckBox.Value
        %             % Get the current axis limits after they've been set
        %             x_limits = xlim(app.CalibrationAxes);
        %             y_limits = ylim(app.CalibrationAxes);
        %
        %             % Find the common range to ensure the 1:1 line properly covers the plot area
        %             min_limit = max([x_limits(1), y_limits(1)]);
        %             max_limit = min([x_limits(2), y_limits(2)]);
        %
        %             % Draw the 1:1 line within these limits for log scale
        %             one_one_line = plot(app.CalibrationAxes, [min_limit, max_limit], [min_limit, max_limit], '--', 'Color', [.7 .7 .7], 'DisplayName', '1:1 Line');
        %         else
        %             % For linear scale, extend the 1:1 line slightly beyond the axis limits
        %             x_limits = xlim(app.CalibrationAxes);
        %             y_limits = ylim(app.CalibrationAxes);
        %
        %             % Find the max extent needed for the line
        %             min_val = min([x_limits(1), y_limits(1)]);
        %             max_val = max([x_limits(2), y_limits(2)]);
        %
        %             % Draw the 1:1 line
        %             one_one_line = plot(app.CalibrationAxes, [min_val, max_val], [min_val, max_val], '--', 'Color', [.7 .7 .7], 'DisplayName', '1:1 Line');
        %         end
        %         % Add 1:1 line to legend
        %         legend_handles = [legend_handles; one_one_line];
        %         legend_labels = [legend_labels; '1:1 Line'];
        %
        %         % Add labels and title
        %         xlabel(app.CalibrationAxes, 'Measured Cl Values');
        %         ylabel(app.CalibrationAxes, 'Reference Cl Values');
        %         title(app.CalibrationAxes, 'Measured vs. Reference Cl Values (All Brackets)');
        %
        %         % Create legend
        %         legend(app.CalibrationAxes, legend_handles, legend_labels, 'Location', 'southeast');
        %
        %         if app.LogscaleCheckBox.Value
        %             % Set both axes to logarithmic scale
        %             set(app.CalibrationAxes, 'XScale', 'log', 'YScale', 'log');
        %         end
        %
        %         % Add grid for better readability
        %         grid(app.CalibrationAxes, 'on');
        %
        %         hold(app.CalibrationAxes, 'off');
        %
        %     end
        %
        % end

        % for only doing single columns
        % function apply_calibration_line_fit(app)
        %     % Get the quadratic fit parameters
        %     %p2 = app.base.project.session.spot.correction.calibration_line.true_vs_meas_quad_fit;
        %
        %     p2(1) = app.x2EditField.Value;
        %     p2(2) = app.xEditField.Value;
        %     p2(3) = app.cEditField.Value;
        %
        %     if isequal(app.ISappliedDropDown.Value, 'Yes')
        %
        %         analyte_corrected = cell(size(app.base.project.session.spot.matrix_composition));
        %
        %         % Process either a single bracket or all brackets
        %         if ~isequal(app.bracket_idDropDown.Value, 0)
        %             % Process just one bracket
        %             brackets_to_process = app.bracket_idDropDown.Value;
        %         else
        %             % Process all brackets
        %             brackets_to_process = 1:size(app.base.project.session.spot.matrix_composition, 1);
        %         end
        %
        %         % Loop through all brackets that need processing
        %         for current_bracket = brackets_to_process
        %             % Get the current table
        %             current_table = app.base.project.session.spot.matrix_composition{current_bracket, app.selected_CAL_for_fit};
        %
        %             % Copy the table to maintain structure
        %             result_table = current_table;
        %
        %             % Extract the selected analyte column
        %             measured_analyte = current_table.(app.AnalyteDropDown.Value);
        %
        %             % Apply the quadratic fit only to non-zero values
        %             corrected_analyte_values = measured_analyte; % Start with original values
        %             non_zero_indices = measured_analyte > 0; % Find non-zero indices
        %
        %             % Apply fit only to non-zero values
        %             if any(non_zero_indices)
        %                 % p2(1)*x^2 + p2(2)*x + p2(3)
        %                 corrected_analyte_values(non_zero_indices) = p2(1)*(measured_analyte(non_zero_indices).^2) + ...
        %                     p2(2)*measured_analyte(non_zero_indices) + p2(3);
        %             end
        %
        %             % Store the results in the output table, keeping the same column name
        %             result_table.(app.AnalyteDropDown.Value) = corrected_analyte_values;
        %             analyte_corrected{current_bracket, app.selected_CAL_for_fit} = result_table;
        %         end
        %
        %             % Save the calculated corrected values
        %             app.base.project.session.spot.correction.calibration_line.corrected_matrix_composition = analyte_corrected;
        %
        %     elseif isequal(app.ISappliedDropDown.Value, 'No')
        %
        %         % Get the sensitivity tables
        %         sensitivity = app.base.project.session.spot.sensitivities;
        %
        %         % Create a copy of sensitivity structure to store preliminary mass fractions
        %         preliminary_mass_fractions = sensitivity;
        %
        %         % Create a cell array with the same structure as sensitivities to store corrected CPS
        %         corrected_cps = cell(size(sensitivity));
        %
        %         % Fill corrected_cps with copies of the original bkgd_corrected_cps
        %         for i = 1:size(corrected_cps, 1)
        %             for j = 1:size(corrected_cps, 2)
        %                 corrected_cps{i, j} = app.base.project.session.spot.bkgd_corrected_cps;
        %             end
        %         end
        %
        %         % Process either a single bracket or all brackets
        %         if ~isequal(app.bracket_idDropDown.Value, 0)
        %             % Process just one bracket
        %             brackets_to_process = app.bracket_idDropDown.Value;
        %         else
        %             % Process all brackets
        %             brackets_to_process = 1:size(app.base.project.session.spot.sensitivities, 1);
        %         end
        %
        %         % Loop through all brackets that need processing
        %         for current_bracket = brackets_to_process
        %             % Get the current table with background corrected CPS
        %             current_cps_table = corrected_cps{current_bracket, app.selected_CAL_for_fit};
        %
        %             % Create a copy to store corrected values
        %             corrected_cps_table = current_cps_table;
        %
        %             % For each bracket and CAL, keep the same table structure
        %             % Only divide where sensitivity is not zero
        %             nonzero_indices = sensitivity{current_bracket, app.selected_CAL_for_fit}.(app.AnalyteDropDown.Value) ~= 0;
        %
        %             % Initialize with the same structure (keep zeros as zeros)
        %             preliminary_mass_fractions{current_bracket, app.selected_CAL_for_fit}.(app.AnalyteDropDown.Value) = sensitivity{current_bracket, app.selected_CAL_for_fit}.(app.AnalyteDropDown.Value);
        %
        %             % Only calculate mass fractions for non-zero sensitivity values
        %             if any(nonzero_indices)
        %                 % Extract corresponding intensities for non-zero sensitivity spots
        %                 relevant_intensities = current_cps_table.(app.AnalyteDropDown.Value)(nonzero_indices);
        %                 relevant_sensitivities = sensitivity{current_bracket, app.selected_CAL_for_fit}.(app.AnalyteDropDown.Value)(nonzero_indices);
        %
        %                 % Calculate preliminary mass fraction
        %                 prelim_mass_frac = relevant_intensities ./ relevant_sensitivities;
        %                 preliminary_mass_fractions{current_bracket, app.selected_CAL_for_fit}.(app.AnalyteDropDown.Value)(nonzero_indices) = prelim_mass_frac;
        %
        %                 % Apply the quadratic fit to the preliminary mass fractions
        %                 % p2(1)*x^2 + p2(2)*x + p2(3)
        %                 corrected_mass_frac = p2(1)*(prelim_mass_frac.^2) + p2(2)*prelim_mass_frac + p2(3);
        %
        %                 % Recalculate the intensities using the corrected mass fractions
        %                 corrected_intensities = corrected_mass_frac .* relevant_sensitivities;
        %
        %                 % Store the corrected intensities back in the table
        %                 corrected_cps_table.(app.AnalyteDropDown.Value)(nonzero_indices) = corrected_intensities;
        %
        %                 corrected_cps_table.(app.AnalyteDropDown.Value)(corrected_cps_table.(app.AnalyteDropDown.Value) < 0) = 0;
        %
        %             end
        %
        %             % Store the corrected table in the cell array
        %             corrected_cps{current_bracket, app.selected_CAL_for_fit} = corrected_cps_table;
        %         end
        %
        %         % Save the corrected background cps values
        %         app.base.project.session.spot.correction.calibration_line.corrected_bkgd_cps = corrected_cps;
        %
        %         % now do the quantification again with the corrected counts
        %         quantification_wrapper(app)
        %
        %     end
        %
        %
        % end
        %

        % able to correct multiple column
        function apply_calibration_line_fit(app)
            % Get the quadratic fit parameters
            %p2 = app.base.project.session.spot.correction.calibration_line.true_vs_meas_quad_fit;

            p2(1) = app.x2EditField.Value;
            p2(2) = app.xEditField.Value;
            p2(3) = app.cEditField.Value;

            if isequal(app.ISappliedDropDown.Value, 'Yes')
                % Initialize correction data structure if it doesn't exist
                if ~isfield(app.base.project.session.spot.correction, 'calibration_line') || ...
                        ~isfield(app.base.project.session.spot.correction.calibration_line, 'corrected_matrix_composition') || ...
                        isempty(app.base.project.session.spot.correction.calibration_line.corrected_matrix_composition)
                    app.base.project.session.spot.correction.calibration_line.corrected_matrix_composition = ...
                        app.base.project.session.spot.matrix_composition;
                end

                % Get the current corrected data
                analyte_corrected = app.base.project.session.spot.correction.calibration_line.corrected_matrix_composition;

                % Process either a single bracket or all brackets
                if ~isequal(app.bracket_idDropDown.Value, 0)
                    % Process just one bracket
                    brackets_to_process = app.bracket_idDropDown.Value;
                else
                    % Process all brackets
                    brackets_to_process = 1:size(app.base.project.session.spot.matrix_composition, 1);
                end

                % Loop through all brackets that need processing
                for current_bracket = brackets_to_process
                    % Get the current table
                    current_table = app.base.project.session.spot.matrix_composition{current_bracket, app.selected_CAL_for_fit};

                    % Get the current corrected table or initialize it
                    if isempty(analyte_corrected{current_bracket, app.selected_CAL_for_fit})
                        result_table = current_table;
                    else
                        result_table = analyte_corrected{current_bracket, app.selected_CAL_for_fit};
                    end

                    % Extract the selected analyte column
                    measured_analyte = current_table.(app.AnalyteDropDown.Value);

                    % Apply the quadratic fit only to non-zero values
                    corrected_analyte_values = measured_analyte; % Start with original values
                    non_zero_indices = measured_analyte > 0; % Find non-zero indices

                    % Apply fit only to non-zero values
                    if any(non_zero_indices)
                        % p2(1)*x^2 + p2(2)*x + p2(3)
                        corrected_analyte_values(non_zero_indices) = p2(1)*(measured_analyte(non_zero_indices).^2) + ...
                            p2(2)*measured_analyte(non_zero_indices) + p2(3);
                    end

                    % Store the results in the output table, keeping the same column name
                    result_table.(app.AnalyteDropDown.Value) = corrected_analyte_values;
                    analyte_corrected{current_bracket, app.selected_CAL_for_fit} = result_table;
                end

                % Save the calculated corrected values
                app.base.project.session.spot.correction.calibration_line.corrected_matrix_composition = analyte_corrected;

            elseif isequal(app.ISappliedDropDown.Value, 'No')
                % Initialize correction data structure if it doesn't exist
                if ~isfield(app.base.project.session.spot.correction, 'calibration_line') || ...
                        ~isfield(app.base.project.session.spot.correction.calibration_line, 'corrected_bkgd_cps') || ...
                        isempty(app.base.project.session.spot.correction.calibration_line.corrected_bkgd_cps)
                    % Initialize with a copy of the original bkgd_corrected_cps
                    corrected_cps = cell(size(app.base.project.session.spot.sensitivities));
                    for i = 1:size(corrected_cps, 1)
                        for j = 1:size(corrected_cps, 2)
                            corrected_cps{i, j} = app.base.project.session.spot.bkgd_corrected_cps;
                        end
                    end
                    app.base.project.session.spot.correction.calibration_line.corrected_bkgd_cps = corrected_cps;
                end

                % Get the sensitivity tables
                sensitivity = app.base.project.session.spot.sensitivities;

                % Create a copy of sensitivity structure to store preliminary mass fractions
                preliminary_mass_fractions = sensitivity;

                % Get the current corrected CPS
                corrected_cps = app.base.project.session.spot.correction.calibration_line.corrected_bkgd_cps;

                % Process either a single bracket or all brackets
                if ~isequal(app.bracket_idDropDown.Value, 0)
                    % Process just one bracket
                    brackets_to_process = app.bracket_idDropDown.Value;
                else
                    % Process all brackets
                    brackets_to_process = 1:size(app.base.project.session.spot.sensitivities, 1);
                end

                % Loop through all brackets that need processing
                for current_bracket = brackets_to_process
                    % Get the current table with background corrected CPS
                    current_cps_table = corrected_cps{current_bracket, app.selected_CAL_for_fit};

                    % Create a copy to store corrected values
                    corrected_cps_table = current_cps_table;

                    % For each bracket and CAL, keep the same table structure
                    % Only divide where sensitivity is not zero
                    nonzero_indices = sensitivity{current_bracket, app.selected_CAL_for_fit}.(app.AnalyteDropDown.Value) ~= 0;

                    % Initialize with the same structure (keep zeros as zeros)
                    preliminary_mass_fractions{current_bracket, app.selected_CAL_for_fit}.(app.AnalyteDropDown.Value) = sensitivity{current_bracket, app.selected_CAL_for_fit}.(app.AnalyteDropDown.Value);

                    % Only calculate mass fractions for non-zero sensitivity values
                    if any(nonzero_indices)
                        % Get the original (uncorrected) intensities
                        original_intensities = app.base.project.session.spot.bkgd_corrected_cps.(app.AnalyteDropDown.Value);

                        % Extract corresponding intensities for non-zero sensitivity spots
                        relevant_intensities = original_intensities(nonzero_indices);
                        relevant_sensitivities = sensitivity{current_bracket, app.selected_CAL_for_fit}.(app.AnalyteDropDown.Value)(nonzero_indices);

                        % Calculate preliminary mass fraction
                        prelim_mass_frac = relevant_intensities ./ relevant_sensitivities;
                        preliminary_mass_fractions{current_bracket, app.selected_CAL_for_fit}.(app.AnalyteDropDown.Value)(nonzero_indices) = prelim_mass_frac;

                        % Apply the quadratic fit to the preliminary mass fractions
                        % p2(1)*x^2 + p2(2)*x + p2(3)
                        corrected_mass_frac = p2(1)*(prelim_mass_frac.^2) + p2(2)*prelim_mass_frac + p2(3);

                        % Recalculate the intensities using the corrected mass fractions
                        corrected_intensities = corrected_mass_frac .* relevant_sensitivities;

                        % Store the corrected intensities back in the table
                        corrected_cps_table.(app.AnalyteDropDown.Value)(nonzero_indices) = corrected_intensities;

                        corrected_cps_table.(app.AnalyteDropDown.Value)(corrected_cps_table.(app.AnalyteDropDown.Value) < 0) = 0;
                    end

                    % Store the corrected table in the cell array
                    corrected_cps{current_bracket, app.selected_CAL_for_fit} = corrected_cps_table;
                end

                % Save the corrected background cps values
                app.base.project.session.spot.correction.calibration_line.corrected_bkgd_cps = corrected_cps;

                % now do the quantification again with the corrected counts
                quantification_wrapper(app)
            end
        end

        %%%%%%%%%%%%%%%% Mass Fraction Calculation %%%%%%%%%%%%%%%%%%%%%

        % 2.
        function calculate_sensitivity_relative_to_the_internal_standard(app)

            % first calculate the analytical sensitivity relative to the
            % internal standard element

            % Get input sensitivities and internal standards
            sensitivities = app.base.project.session.spot.sensitivities;
            internal_std = app.base.project.session.spot.internal_standard;

            % Initialize output cell array
            app.sensitivity_relative_to_internal_standard = cell(size(sensitivities,1), size(sensitivities,2));

            % Process each table in the cell array
            for cal_idx = 1:size(sensitivities,2)
                for bracket_idx = 1:size(sensitivities,1)
                    if ~isempty(sensitivities{bracket_idx,cal_idx})
                        % Get current sensitivity table and internal standards
                        curr_sens = sensitivities{bracket_idx,cal_idx};
                        curr_std = internal_std{bracket_idx,cal_idx};
                        curr_cps = app.base.project.session.spot.correction.calibration_line.corrected_bkgd_cps{bracket_idx,cal_idx};
                    else
                        continue
                    end
                    % Get column names
                    colNames = curr_sens.Properties.VariableNames;

                    % Convert to matrix for calculations
                    sens_matrix = table2array(curr_sens);
                    relative_sens = zeros(size(sens_matrix));

                    % Process each row
                    for unk = 1:size(sens_matrix, 1)
                        % Check if internal standard exists
                        if ~isempty(curr_std{unk})
                            % Get internal standard element name and column index
                            std_element = curr_std{unk};

                            if isequal(std_element, 'Total Oxides')

                                % let the analyte with the highest net-cps be
                                % the internal standard, same as in old
                                % SILLS

                                if all(curr_cps{unk, :} == 0)
                                    % If there was no matrix and background
                                    % set, the correction.calibration_line.corrected_bkgd_cps are all
                                    % 0, therefore no relative sensitivity
                                    % needs to be calculated
                                    continue
                                else

                                    % For total mass calculations, the
                                    % internal standards has been defined
                                    % here
                                    % in theory, the IS is from now on the
                                    % analyte with the relative_sens of 1

                                    % i need to think about how to process
                                    % this further

                                    [~, intstd_element_indices] = max(curr_cps{unk, :});
                                    intstd_element_name = curr_cps.Properties.VariableNames(intstd_element_indices);

                                    % Check if internal standard element exists in sensitivities
                                    % if a given CAL has not all analytes,
                                    % the analyte with theoretically the
                                    % highest net-cps cannot be used as IS.
                                    % Thereofore it searches for the
                                    % second-to-max analyte
                                    sensitivities_table = app.base.project.session.spot.sensitivities{bracket_idx, cal_idx};
                                    while ~ismember(intstd_element_name{1}, sensitivities_table.Properties.VariableNames)
                                        % Remove current max element and find next highest CPS
                                        temp_cps = curr_cps{unk, :};
                                        temp_cps(intstd_element_indices) = -Inf; % Set current max to -Inf
                                        [~, intstd_element_indices] = max(temp_cps);
                                        intstd_element_name = curr_cps.Properties.VariableNames(intstd_element_indices);
                                    end

                                    internal_standard_sensitivity = app.base.project.session.spot.sensitivities{bracket_idx, cal_idx}.(intstd_element_name{1})(unk);
                                    all_analytes_sensitivity = table2array(app.base.project.session.spot.sensitivities{bracket_idx, cal_idx}(unk,:));

                                    relative_sens(unk,:) = all_analytes_sensitivity ./ internal_standard_sensitivity;

                                    internal_standard_indices(unk,:) = intstd_element_indices;
                                end

                            else

                                std_col = find(strcmp(colNames, std_element));
                                % Get internal standard value
                                std_value = sens_matrix(unk, std_col);
                                % Calculate relative sensitivities for this row
                                relative_sens(unk,:) = sens_matrix(unk,:) ./ std_value;

                            end
                        end
                    end

                    % Convert result back to table and store in cell array
                    app.sensitivity_relative_to_internal_standard{bracket_idx,cal_idx} = ...
                        array2table(relative_sens, 'VariableNames', colNames);

                    % store these names temporary, they get then used in the
                    % total_mass_calculation function
                    if exist('internal_standard_indices')
                        app.internal_standard_indices_total_mass{bracket_idx,cal_idx} = ...
                            array2table(internal_standard_indices);
                    end
                end
            end
        end

        % 3. %%%%%%%%%%%%%%%%%%%this is old %%%%%%%%%%%%%%%%%%%%%%%%%%%%
        function calculate_matrix_mass_fractions(app)

            %%%%% this function calculates the mass fractions in several
            %%%%% passes through the dataset. it makes one pass per
            %%%%% calibrator
            %%%%% reference material. this is done to make a sequential
            %%%%% data reduction possible, using the values calculated with
            %%%%% one CAL as an internal standard for another CAL

            cal_indices = strcmp(app.base.project.session.spot.type, "CAL");
            % Get reference materials only for CAL spots
            cal_materials = app.base.project.session.spot.referencematerial(cal_indices);
            % Get unique names, excluding "None" and "Select Reference Material"
            unique_CALs = unique(cal_materials);
            % Find all Unknown spots
            all_UNK = find(~strcmp(app.base.project.session.spot.type, "CAL"));

            % calculate the mass fractions for all unknowns

            for cal_idx = 1:size(app.base.project.session.spot.sensitivities,2)
                for bracket_idx = 1:size(app.base.project.session.spot.sensitivities,1)

                    curr_cps = app.base.project.session.spot.correction.calibration_line.corrected_bkgd_cps{bracket_idx,cal_idx};

                    % Initialize the matrix_composition table for current CAL
                    current_matrix_composition = array2table(zeros(length(app.base.project.session.spot.signal_starts), numel(app.base.project.session.spot.analytes)), ...
                        'VariableNames', curr_cps.Properties.VariableNames);
                    current_matrix_limit_of_detection = array2table(zeros(length(app.base.project.session.spot.signal_starts), numel(app.base.project.session.spot.analytes)), ...
                        'VariableNames', curr_cps.Properties.VariableNames);

                    for i = 1:numel(all_UNK)

                        unk = all_UNK(i);

                        if isempty(app.base.project.session.spot.internal_standard{bracket_idx,cal_idx}{unk})
                            continue
                        end

                        if isempty(app.base.project.session.spot.internal_standard_mass_fraction{bracket_idx,cal_idx}{unk}) && ~isequal(app.base.project.session.spot.internal_standard{bracket_idx,cal_idx}{unk}, 'Total Oxides')
                            continue
                        end

                        if isempty(app.base.project.session.spot.internal_standard_oxide_wt{bracket_idx,cal_idx}{unk}) && isequal(app.base.project.session.spot.internal_standard{bracket_idx,cal_idx}{unk}, 'Total Oxides')
                            continue
                        end

                        if isempty(app.base.project.session.spot.Fe_ratio_for_total_oxide{bracket_idx,cal_idx}{unk}) && isequal(app.base.project.session.spot.internal_standard{bracket_idx,cal_idx}{unk}, 'Total Oxides')
                            continue
                        end

                        if ischar(app.base.project.session.spot.internal_standard_mass_fraction{bracket_idx,cal_idx}{unk}) && ...
                                startsWith(app.base.project.session.spot.internal_standard_mass_fraction{bracket_idx,cal_idx}{unk}, 'from') && ~isequal(app.base.project.session.spot.internal_standard{bracket_idx,cal_idx}{unk}, 'Total Oxides')
                            continue
                        end

                        if all(curr_cps{unk, :} == 0)
                            % If there was no matrix and background
                            % set, the correction.calibration_line.corrected_bkgd_cps are all
                            % 0, therefore no relative sensitivity
                            % needs to be calculated
                            continue
                        end

                        % Initialize mass_fractions_matrix with zeros
                        mass_fractions_matrix = zeros(1, width(curr_cps));
                        LOD_matrix = zeros(1, width(curr_cps));

                        % Get column names from sensitivity table
                        sensitivity_cols = app.sensitivity_relative_to_internal_standard{bracket_idx,cal_idx}.Properties.VariableNames;

                        internal_standard = app.base.project.session.spot.internal_standard{bracket_idx,cal_idx}{unk};

                        if isequal(internal_standard, 'Total Oxides')

                            [internal_standard_intensity, internal_standard_mass_fraction, internal_standard_name] = total_mass_calculation(app, bracket_idx, cal_idx, unk);

                        else % this is for the normal case
                            internal_standard_name = internal_standard;
                            internal_standard_intensity = curr_cps.(internal_standard_name)(unk);
                            internal_standard_mass_fraction = app.base.project.session.spot.internal_standard_mass_fraction{bracket_idx,cal_idx}{unk};

                        end

                        % Calculate mass fractions only for columns present in
                        % sensitivity table since this table represents the analytes
                        % that are also in the external standard
                        for col = 1:length(sensitivity_cols)
                            if ismember(sensitivity_cols{col}, curr_cps.Properties.VariableNames)

                                col_idx = find(strcmp(curr_cps.Properties.VariableNames, sensitivity_cols{col}));

                    
                                % actual calculation
                                mass_fractions_matrix(col_idx) = (curr_cps.(sensitivity_cols{col})(unk) / internal_standard_intensity) * ...
                                    (internal_standard_mass_fraction / ...
                                    app.sensitivity_relative_to_internal_standard{bracket_idx,cal_idx}.(sensitivity_cols{col})(unk));
                                % limits of detection
                                LOD_matrix(col_idx) = calculate_limits_of_detection_matrix(app, bracket_idx, cal_idx, unk, sensitivity_cols, col, internal_standard_name, internal_standard_mass_fraction);

                            end
                        end

                        % Update the specific row in current matrix_composition with calculated mass fractions
                        current_matrix_composition{unk,:} = mass_fractions_matrix;
                        % Update the specific row in current
                        % matrix_limit_of_detection with calculated
                        % detection limit
                        current_matrix_limit_of_detection{unk,:} = LOD_matrix;

                    end

                    % Store the matrix_composition table for current CAL in the cell array
                    app.base.project.session.spot.correction.calibration_line.corrected_matrix_composition{bracket_idx,cal_idx} = current_matrix_composition;
                    % Store the matrix_limits_of_detection table for current CAL in the cell array
                    app.base.project.session.spot.correction.calibration_line.corrected_matrix_limits_of_detection{bracket_idx,cal_idx} = current_matrix_limit_of_detection;

                    % delete the empyt matrix_composition tables to make it
                    % look pretty
                    if isempty(app.base.project.session.spot.sensitivities{bracket_idx,cal_idx}) || ...
                            isequal(app.base.project.session.spot.sensitivities{bracket_idx,cal_idx}, zeros(0,0))
                        app.base.project.session.spot.correction.calibration_line.corrected_matrix_composition{bracket_idx,cal_idx} = zeros(0,0);
                    end
                    if isempty(app.base.project.session.spot.sensitivities{bracket_idx,cal_idx}) || ...
                            isequal(app.base.project.session.spot.sensitivities{bracket_idx,cal_idx}, zeros(0,0))
                        app.base.project.session.spot.correction.calibration_line.corrected_matrix_limits_of_detection{bracket_idx,cal_idx} = zeros(0,0);
                    end

                end
            end

            % Dynamic passes based on number of unique CALs
            % this is necessary for the sequential data reduction
            max_passes = length(unique_CALs);
            for pass = 1:max_passes
                changes_made = false; % Flag to track if any values were updated in this pass

                for cal_idx = 1:size(app.base.project.session.spot.sensitivities, 2)
                    for bracket_idx = 1:size(app.base.project.session.spot.sensitivities, 1)

                        curr_cps = app.base.project.session.spot.correction.calibration_line.corrected_bkgd_cps{bracket_idx,cal_idx};

                        current_matrix_composition = array2table(zeros(length(app.base.project.session.spot.signal_starts), numel(app.base.project.session.spot.analytes)), ...
                            'VariableNames', curr_cps.Properties.VariableNames);
                        current_matrix_limit_of_detection = array2table(zeros(length(app.base.project.session.spot.signal_starts), numel(app.base.project.session.spot.analytes)), ...
                            'VariableNames', curr_cps.Properties.VariableNames);

                        for i = 1:numel(all_UNK)
                            unk = all_UNK(i);

                            % Skip if no internal standard is defined
                            if isempty(app.base.project.session.spot.internal_standard{bracket_idx,cal_idx}{unk})
                                continue
                            end

                            % Get internal standard element for current spot
                            is_element = app.base.project.session.spot.internal_standard{bracket_idx,cal_idx}{unk};

                            % Check if internal_standard_mass_fraction contains 'from' indicating we should use another CAL's result
                            if ischar(app.base.project.session.spot.internal_standard_mass_fraction{bracket_idx,cal_idx}{unk}) && ...
                                    startsWith(app.base.project.session.spot.internal_standard_mass_fraction{bracket_idx,cal_idx}{unk}, 'from')  && ~isequal(app.base.project.session.spot.internal_standard{bracket_idx,cal_idx}{unk}, 'Total Oxides')

                                % Extract source CAL name
                                source_cal = extractAfter(app.base.project.session.spot.internal_standard_mass_fraction{bracket_idx,cal_idx}{unk}, 'from ');
                                source_cal_idx = find(strcmp(unique_CALs, source_cal));

                                % Get calculated value from source CAL if available
                                if ~isempty(source_cal_idx) && ~isempty(app.base.project.session.spot.correction.calibration_line.corrected_matrix_composition{bracket_idx,source_cal_idx})
                                    calculated_is_value = app.base.project.session.spot.correction.calibration_line.corrected_matrix_composition{bracket_idx,source_cal_idx}{unk,is_element};

                                    if calculated_is_value == 0
                                        current_matrix_composition{unk,:} = table2array(app.base.project.session.spot.correction.calibration_line.corrected_matrix_composition{bracket_idx,cal_idx}(unk,:));
                                        current_matrix_limit_of_detection{unk,:} = table2array(app.base.project.session.spot.correction.calibration_line.corrected_matrix_limits_of_detection{bracket_idx,cal_idx}(unk,:));
                                    elseif calculated_is_value > 0
                                        % Store original value
                                        original_is_mass_fraction = app.base.project.session.spot.internal_standard_mass_fraction{bracket_idx,cal_idx}{unk};
                                        old_values = table2array(app.base.project.session.spot.correction.calibration_line.corrected_matrix_composition{bracket_idx,cal_idx}(unk,:));

                                        % Use calculated value for this calculation
                                        app.base.project.session.spot.internal_standard_mass_fraction{bracket_idx,cal_idx}{unk} = calculated_is_value;

                                        % Calculate mass fractions with sensitivity table
                                        sensitivity_cols = app.sensitivity_relative_to_internal_standard{bracket_idx,cal_idx}.Properties.VariableNames;
                                        mass_fractions_matrix = zeros(1, width(curr_cps));
                                        LOD_matrix = zeros(1, width(curr_cps));

                                        for col = 1:length(sensitivity_cols)
                                            if ismember(sensitivity_cols{col}, curr_cps.Properties.VariableNames)
                                                col_idx = find(strcmp(curr_cps.Properties.VariableNames, sensitivity_cols{col}));

                                                internal_standard_name = app.base.project.session.spot.internal_standard{bracket_idx,cal_idx}{unk};
                                                internal_standard_intensity = curr_cps.(char(internal_standard_name))(unk);
                                                internal_standard_mass_fraction = app.base.project.session.spot.internal_standard_mass_fraction{bracket_idx,cal_idx}{unk};


                                                % actual calculation
                                                mass_fractions_matrix(col_idx) = (curr_cps.(sensitivity_cols{col})(unk) / internal_standard_intensity) * ...
                                                    (internal_standard_mass_fraction / ...
                                                    app.sensitivity_relative_to_internal_standard{bracket_idx,cal_idx}.(sensitivity_cols{col})(unk));

                                                % limits of detection
                                                LOD_matrix(col_idx) = calculate_limits_of_detection_matrix(app, bracket_idx, cal_idx, unk, sensitivity_cols, col, internal_standard_name, internal_standard_mass_fraction);

                                            end
                                        end

                                        % Restore original value
                                        app.base.project.session.spot.internal_standard_mass_fraction{bracket_idx,cal_idx}{unk} = original_is_mass_fraction;

                                        % save the data
                                        current_matrix_composition{unk,:} = mass_fractions_matrix;
                                        current_matrix_limit_of_detection{unk,:} = LOD_matrix;

                                        % Check if values changed
                                        if any(abs(mass_fractions_matrix - old_values) > 1e-10)
                                            changes_made = true;
                                        end
                                    end
                                end
                            else
                                current_matrix_composition{unk,:} = table2array(app.base.project.session.spot.correction.calibration_line.corrected_matrix_composition{bracket_idx,cal_idx}(unk,:));
                                current_matrix_limit_of_detection{unk,:} = table2array(app.base.project.session.spot.correction.calibration_line.corrected_matrix_limits_of_detection{bracket_idx,cal_idx}(unk,:));
                            end
                        end

                        app.base.project.session.spot.correction.calibration_line.corrected_matrix_composition{bracket_idx,cal_idx} = current_matrix_composition;
                        % Store the matrix_limits_of_detection table for current CAL in the cell array
                        app.base.project.session.spot.correction.calibration_line.corrected_matrix_limits_of_detection{bracket_idx,cal_idx} = current_matrix_limit_of_detection;
                    end
                end

                % If no changes were made in this pass, we can stop
                if ~changes_made && pass > 1
                    break;
                end
            end
        end

        % this function is called in calculate_matrix_mass_fractions
        % it outputs the intensity and mass fraction of the major element
        % with the highest net-count rate (like in the old SILLS). The rest
        % is calculated in the calculate_matrix_mass_fractions function
        function [intstd_elements_intensity , internal_standard_mass_fraction, intstd_elements_name] = total_mass_calculation(app, bracket_idx, cal_idx, unk)

            % Step 1: Calculate preliminary normalized mass fractions
            % the IS is taken from the
            % calculate_sensitivity_relative_to_the_internal_standard
            % function where the IS for the total mass calcualtion is
            % defined as the analyte with the highest net cps.

            curr_cps = app.base.project.session.spot.correction.calibration_line.corrected_bkgd_cps{bracket_idx,cal_idx};

            sensitivity = table2array(app.base.project.session.spot.sensitivities{bracket_idx, cal_idx}(unk, :));
            intensity = table2array(curr_cps(unk,:));

            % Get column names from sensitivity table
            sensitivity_elements = app.base.project.session.spot.sensitivities{bracket_idx, cal_idx}(unk, :).Properties.VariableNames;

            % Get matching column indices from intensity table
            [~, intensity_indices] = ismember(sensitivity_elements, curr_cps.Properties.VariableNames);

            % Extract only the matching columns from intensity
            matched_intensity = intensity(:, intensity_indices);

            preliminary_mass_fraction = matched_intensity ./ sensitivity;

            intstd_element_index = table2array(app.internal_standard_indices_total_mass{bracket_idx, cal_idx}(unk,:));

            normalized_mass_fraction = preliminary_mass_fraction ./ preliminary_mass_fraction(intstd_element_index);

            % up to this point, this was limited by which elements are in a
            % given CAL as seen in the sensitivity

            % Step 2: Handle Fe2+/Fe3+ ratio like old SILLS
            %%%%%%%%%%% For Julia!!! %%%%%%%%%%%%

            elements_to_total = app.base.project.session.spot.sensitivities{bracket_idx, cal_idx}.Properties.VariableNames;

            if any(contains(app.base.project.session.oxides.oxide_names, 'FeO')) && any(contains(elements_to_total, 'Fe'))

                fe_idx = find(contains(elements_to_total, 'Fe'));

                fe_ratio = app.base.project.session.spot.Fe_ratio_for_total_oxide{bracket_idx, cal_idx}{unk};

                if ~isempty(fe_ratio) && ~isequal(fe_ratio, 1)
                    if any(fe_idx)
                        fe_total = normalized_mass_fraction(fe_idx);
                        % Calculate Fe2O3 and FeO using the constants from old script
                        % 0.699480/0.777314 is the ratio of Fe in Fe2O3 vs FeO
                        fe_constant = 0.699480/0.777314;

                        % Calculate fractions using the same formula as old script
                        fe2o3_fraction = fe_total * (1-(1/(1+(fe_constant)*((1-fe_ratio)/fe_ratio))));
                        feo_fraction = fe_total * (1/(1+(fe_constant)*((1-fe_ratio)/fe_ratio)));

                        % Update Fe calculations
                        normalized_mass_fraction(fe_idx) = feo_fraction;
                        % Add Fe2O3 contribution
                        normalized_mass_fraction = [normalized_mass_fraction fe2o3_fraction];
                        elements_to_total = [elements_to_total {'Fe2O3'}];
                    end
                end
            end

            % Step 3: Convert to oxides
            oxide_factors = app.base.project.session.oxides.conversion_factors;
            oxide_wt_percent = zeros(size(normalized_mass_fraction));

            for j = 1:length(elements_to_total)
                element = elements_to_total{j};
                base_element = regexp(element, '^[A-Za-z]+', 'match');
                if ~isempty(base_element) && isKey(oxide_factors, base_element{1}) && ~strcmp(element, 'Fe2O3')
                    oxide_wt_percent(j) = normalized_mass_fraction(j) * oxide_factors(base_element{1});
                elseif strcmp(element, 'Fe2O3')
                    oxide_wt_percent(j) = normalized_mass_fraction(j) * 1.4297;
                end
            end

            % Step 4: Normalize to input total oxide wt%
            if ~isempty(app.base.project.session.spot.Fe_ratio_for_total_oxide{bracket_idx, cal_idx}{unk})
                std_value = app.base.project.session.spot.internal_standard_oxide_wt{bracket_idx, cal_idx}{unk};
                if ~isempty(std_value)
                    oxide_wt_percent = oxide_wt_percent * (std_value / sum(oxide_wt_percent));
                end
            end

            % so this oxide_wt_percent is now the correct value for a given
            % cal_idx, bracket_idx and unk

            % Step 5: Convert major oxide weight percentages back to µg/g
            mass_fractions_ugg = zeros(size(oxide_wt_percent));
            for j = 1:length(elements_to_total)
                element = elements_to_total{j};
                base_element = regexp(element, '^[A-Za-z]+', 'match');
                if ~isempty(base_element)
                    if contains(element, 'Fe') && ~strcmp(element, 'Fe2O3')
                        % Store Fe2+ value
                        mass_fractions_ugg(j) = (oxide_wt_percent(j) / oxide_factors('Fe')) * 10000;
                        %major_elements_names{j} = 'Fe2';

                    elseif strcmp(element, 'Fe2O3')
                        % Store Fe3+ value
                        mass_fractions_ugg(j) = (oxide_wt_percent(j) / 1.4297) * 10000;
                        elements_to_total{j} = 'Fe3';

                    elseif isKey(oxide_factors, base_element{1})
                        mass_fractions_ugg(j) = (oxide_wt_percent(j) / oxide_factors(base_element{1})) * 10000;
                    end
                end
            end

            % Initialize mass_fractions table with Fe2+/Fe3+ columns
            % major_mass_fractions = array2table(zeros(size(app.base.project.session.spot.correction.calibration_line.corrected_bkgd_cps,1), length([major_elements_names, {'Fe2', 'Fe3'}])), ...
            %     'VariableNames', [major_elements_names, {'Fe2', 'Fe3'}]);

            mass_fractions = array2table(zeros(size(app.base.project.session.spot.correction.calibration_line.corrected_bkgd_cps,1), length(elements_to_total)), ...
                'VariableNames', elements_to_total);

            mass_fractions{unk, elements_to_total} = mass_fractions_ugg;

            if any(contains(app.base.project.session.oxides.oxide_names, 'FeO')) && any(contains(elements_to_total, 'Fe'))

                % Find the column that contains 'Fe' in the header
                fe_col = find(contains(mass_fractions.Properties.VariableNames, 'Fe') & ...
                    ~strcmp(mass_fractions.Properties.VariableNames, 'Fe2') & ...
                    ~strcmp(mass_fractions.Properties.VariableNames, 'Fe3'));

                mass_fractions.Fe2 = table2array(mass_fractions(:, fe_col));

                % Sum Fe2 and Fe3 to ge the total Fe
                if any(contains(mass_fractions.Properties.VariableNames, 'Fe3'))
                    mass_fractions{:, fe_col} = mass_fractions.Fe2 + mass_fractions.Fe3;
                end
                % do this properly if the Fe2 and Fe3 µg/g should be stored
                % somewhere
                % if ~isfield(app, 'Fe2_3')
                %     app.Fe2_3 = array2table(zeros(size(app.base.project.session.spot.correction.calibration_line.corrected_bkgd_cps,1), 2), ...
                %         'VariableNames', {'Fe2', 'Fe3'});
                %     % Store Fe2 and Fe3 in app properties without overwriting previous values
                %     app.Fe2_3.Fe2(unk) = major_mass_fractions.Fe2(unk);
                %     app.Fe2_3.Fe3(unk) = major_mass_fractions.Fe3(unk);
                % end

                % Remove Fe2 and Fe3 columns from major_mass_fractions
                mass_fractions.Fe2 = [];
                if any(contains(mass_fractions.Properties.VariableNames, 'Fe3'))
                    mass_fractions.Fe3 = [];
                end
            end

            % for function output
            intstd_elements_name = curr_cps.Properties.VariableNames(intstd_element_index);
            intstd_elements_intensity = table2array(curr_cps(unk, intstd_element_index));

            internal_standard_mass_fraction = mass_fractions.(intstd_elements_name{1})(unk);

        end


        function LOD_matrix = calculate_limits_of_detection_matrix(app, bracket_idx, cal_idx, unk, sensitivity_cols, col, internal_standard_name, internal_standard_mass_fraction)
            % According to Pettke 2012

            background_start = app.base.project.session.spot.background_starts;
            background_end = app.base.project.session.spot.background_ends;

            % calculate the mean for the matrix
            matrix_1_start = app.base.project.session.spot.matrix_1_starts;
            matrix_1_end = app.base.project.session.spot.matrix_1_ends;

            matrix_2_start = app.base.project.session.spot.matrix_2_starts;
            matrix_2_end = app.base.project.session.spot.matrix_2_ends;

            matrix_3_start = app.base.project.session.spot.matrix_3_starts;
            matrix_3_end = app.base.project.session.spot.matrix_3_ends;

            % case of full measurement input
            if isequal(app.base.project.session.config.datatype, 'Continuous')
                signal = app.base.project.session.ms_data;
            end

            % case of single_spot input
            if isequal(app.base.project.session.config.datatype, 'Single')
                signal = app.base.project.session.spot.ms_data_single_spot{unk};
            end

            % mean_background_cps = table2array(mean(signal(background_start{unk}:background_end{unk}, 2:end), 1));
            mean_background_cps = mean(signal.(sensitivity_cols{col})(background_start{unk}:background_end{unk}));

            number_of_sweeps_bg = numel(background_start{unk}:background_end{unk});

            number_of_sweeps_matrix = numel(matrix_1_start{unk}:matrix_1_end{unk}) + numel(matrix_2_start{unk}:matrix_2_end{unk}) + numel(matrix_3_start{unk}:matrix_3_end{unk});

            % i dont know why this is done like that but the old SILLS also
            % does it and it seems to give the same values. Also in Pettke
            % 2012 it states "sensitivity of element in cps/µg/g as derived from the external standard multiplied by the relative sensitivity factor"
            %sensitivity = app.sensitivity_relative_to_internal_standard{bracket_idx,cal_idx}.(sensitivity_cols{col})(unk) .* (app.base.project.session.spot.correction.calibration_line.corrected_bkgd_cps.(app.base.project.session.spot.internal_standard{bracket_idx,cal_idx}{unk})(unk)/app.base.project.session.spot.internal_standard_mass_fraction{bracket_idx,cal_idx}{unk});
            sensitivity = app.sensitivity_relative_to_internal_standard{bracket_idx,cal_idx}.(sensitivity_cols{col})(unk) .* (app.base.project.session.spot.correction.calibration_line.corrected_bkgd_cps{bracket_idx,cal_idx}.(char(internal_standard_name))(unk)/internal_standard_mass_fraction);

            % dwelltime in seconds
            dwelltime = app.base.project.session.dwelltimes.(sensitivity_cols{col});

            LOD_matrix = (3.29 * (mean_background_cps * dwelltime * number_of_sweeps_matrix * (1 + number_of_sweeps_matrix / number_of_sweeps_bg)).^0.5 + 2.71)...
                / (number_of_sweeps_matrix * dwelltime * sensitivity);

        end


        function quantification_wrapper(app)

            % Matrix/Host
            calculate_sensitivity_relative_to_the_internal_standard(app)
            calculate_matrix_mass_fractions(app)

        end
        
        %%%%% GUI stuff %%%%%%%%%%%

        function update_results_and_quality_control(app)

            % Get indices where type is "CAL"
            cal_indices = strcmp(app.base.project.session.spot.type, "CAL");
            % Get reference materials only for CAL spots
            cal_materials = app.base.project.session.spot.referencematerial(cal_indices);
            % Get unique names, excluding "None" and "Select Reference Material"
            unique_names = unique(cal_materials);
            unique_names = unique_names(~strcmp(unique_names, 'None'));
            validCALs = unique_names(~strcmp(unique_names, 'Select Reference Material'));

            % Populate CAL dropdown
            app.CalibratorDropDown_2.Items = validCALs;
            app.CalibratorDropDown_2.ItemsData = 1:numel(app.CalibratorDropDown_2.Items);

            % Get selected reference material and find matching indices
            selectedRM = validCALs(app.CalibratorDropDown_2.Value);
            rmIndices = strcmp(app.base.project.session.spot.referencematerial, selectedRM);

            % Filter bracket IDs for selected reference material
            filteredBracketIds = app.base.project.session.spot.bracket_id(rmIndices);
            uniqueBracketIds = unique(filteredBracketIds);
            uniqueBracketIds = uniqueBracketIds(uniqueBracketIds ~= 0);

            % Update bracket ID dropdown
            app.BracketidDropDown_3.Items = string(uniqueBracketIds);
            app.BracketidDropDown_3.ItemsData = uniqueBracketIds;

            % at startup the selected_CAL is empty, make it 1 so that
            % something is plotted anyways
            if isempty(app.selected_CAL)
                app.selected_CAL = 1;
            end
            % Set default bracket_id if empty - use first available bracket_id
            if isempty(app.selected_bracket_id)
                app.selected_bracket_id = uniqueBracketIds(1);
                app.BracketidDropDown_3.Value = uniqueBracketIds(1);
            end


            %%%%%%%%%%%%%%%%%%%%%%%%%% Quality Control %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            qcrm_indices = strcmp(app.base.project.session.spot.type, "QCRM");

            if any(qcrm_indices)
                % Get reference materials only for QCRM spots
                qcrm_materials = app.base.project.session.spot.referencematerial(qcrm_indices);
                % Get unique names, excluding "None" and "Select Reference Material"
                unique_names = unique(qcrm_materials);
                unique_names = unique_names(~strcmp(unique_names, 'None'));
                validQCRMs = unique_names(~strcmp(unique_names, 'Select Reference Material'));

                % Populate QCRM dropdown
                app.QualityControlReferenceMaterialDropDown_2.Items = validQCRMs;
                app.QualityControlReferenceMaterialDropDown_2.ItemsData = app.QualityControlReferenceMaterialDropDown_2.Items;

                % populate the CAL and bracket_id dropdown
                app.CalibratorDropDown_3.Items = app.CalibratorDropDown_2.Items;
                app.CalibratorDropDown_3.ItemsData = app.CalibratorDropDown_2.ItemsData;
                app.BracketidDropDown_2.Items = string(uniqueBracketIds);
                app.BracketidDropDown_2.ItemsData = uniqueBracketIds;

                update_results_table(app)
                update_qcrm_plot_cal_line(app)
            end

        end


        function update_qcrm_plot_cal_line(app)

            % get the Indices of the chosen QCRM from the dropdown
            % from spot.referencematerials
            refMaterial = app.QualityControlReferenceMaterialDropDown_2.Value;
            indices = find(strcmp(app.base.project.session.spot.referencematerial, refMaterial));

            %%%%%%%%%% calculate the ratios %%%%%%%
            % of the measured values vs the published values

            if isequal(app.DisplayDropDown.Value, 'Raw')
                values = app.base.project.session.spot.matrix_composition{app.BracketidDropDown_2.Value, app.CalibratorDropDown_3.Value}(indices,:);
            elseif isequal(app.DisplayDropDown.Value, 'Calibration line corrected')
                values = app.base.project.session.spot.correction.calibration_line.corrected_matrix_composition{app.BracketidDropDown_2.Value, app.CalibratorDropDown_3.Value}(indices,:);
            end


            % Find indices of rows and columns that have at least one non-zero value
            nonZeroRows = any(table2array(values) ~= 0, 2);  % check rows
            nonZeroCols = any(table2array(values) ~= 0);     % check columns

            % Keep only rows and columns that have non-zero values
            qcrm_massfractions = values(nonZeroRows, nonZeroCols);

            %cleaned_columnheaders = cellfun(@(x) regexprep(x, '\d', ''), qcrm_massfractions.Properties.VariableNames, 'UniformOutput', false);
            % find the name of the referencematerial in

            qcrm_pub_idx = find(strcmp(app.base.project.session.referencematerials.names, refMaterial));
            qcrm_pub_data = app.base.project.session.referencematerials.data{qcrm_pub_idx};

            % Get base element names by removing numbers from mass_fraction column names
            base_elements = regexprep(qcrm_massfractions.Properties.VariableNames, '\d+', '');

            % Find matching elements in pub_data
            [~, pub_indices] = ismember(base_elements, qcrm_pub_data.Properties.VariableNames);

            % Get only the columns where we found matches
            valid_matches = pub_indices > 0;
            mass_cols = qcrm_massfractions(:, valid_matches);
            pub_vals = repmat(table2array(qcrm_pub_data(:, pub_indices(valid_matches))), size(mass_cols, 1), 1);

            % Calculate ratios
            ratio_values = table2array(mass_cols) ./ pub_vals;

            % Create results table with original isotope names
            ratios = array2table(ratio_values, 'VariableNames', qcrm_massfractions.Properties.VariableNames(valid_matches));

            % these ratios are what I need for the secondary standard
            % correction

            %%%%%%%%%% plot that shit %%%%%%%%

            % first plot a line at y=1.
            % then plot the ratios. the columnheaders should be on the
            % x-axis and the ratio value on the y-axis.

            cla(app.QCRMAxes)
            hold(app.QCRMAxes, 'on');

            % Set y-axis to log scale
            app.QCRMAxes.YScale = 'log';

            % Add shaded regions around y=1
            xCoord = [1 width(ratios) width(ratios) 1];

            % Light green region (±10%)
            yCoord = [0.9 0.9 1.1 1.1];
            fill(app.QCRMAxes, xCoord, yCoord, [0.9 1 0.9], 'EdgeColor', 'none', 'FaceAlpha', 0.5);

            % Yellow regions (10-20%)
            yCoordLower = [0.8 0.8 0.9 0.9];
            yCoordUpper = [1.1 1.1 1.2 1.2];
            fill(app.QCRMAxes, xCoord, yCoordLower, [1 1 0.8], 'EdgeColor', 'none', 'FaceAlpha', 0.5);
            fill(app.QCRMAxes, xCoord, yCoordUpper, [1 1 0.8], 'EdgeColor', 'none', 'FaceAlpha', 0.5);

            % Light orange regions (20-30%)
            yCoordLower = [0.7 0.7 0.8 0.8];
            yCoordUpper = [1.2 1.2 1.3 1.3];
            fill(app.QCRMAxes, xCoord, yCoordLower, [1 0.9 0.8], 'EdgeColor', 'none', 'FaceAlpha', 0.5);
            fill(app.QCRMAxes, xCoord, yCoordUpper, [1 0.9 0.8], 'EdgeColor', 'none', 'FaceAlpha', 0.5);

            % Light red regions (>30% and <70%)
            yCoordLower = [0.00001 0.00001 0.7 0.7];
            yCoordUpper = [1.3 1.3 10000 10000];
            fill(app.QCRMAxes, xCoord, yCoordLower, [1 0.8 0.8], 'EdgeColor', 'none', 'FaceAlpha', 0.4);
            fill(app.QCRMAxes, xCoord, yCoordUpper, [1 0.8 0.8], 'EdgeColor', 'none', 'FaceAlpha', 0.4);

            % Get current y-limits
            ymin = min(min(table2array(ratios)));
            ymax = max(max(table2array(ratios)));

            % Calculate symmetric limits in log space around y=1
            maxDistance = max(abs(log10(ymin)), abs(log10(ymax)));
            app.QCRMAxes.YLim = [10^(-maxDistance) 10^maxDistance];

            % Plot horizontal line at y=1
            yline(app.QCRMAxes, 1, 'k--', 'LineWidth', 1);

            % Plot data with brown lines and dark green markers
            for i = 1:height(ratios)
                plot(app.QCRMAxes, 1:width(ratios), table2array(ratios(i,:)), ...
                    '-d', 'Color', [0.4 0.26 0.13], ... % Darker brown
                    'MarkerFaceColor', [0.0 0.4 0.0], ... % Dark green
                    'MarkerEdgeColor', [0.0 0.4 0.0]);    % Dark green
            end

            % Add grids with solid lines
            app.QCRMAxes.YGrid = 'on';
            app.QCRMAxes.XGrid = 'on';
            app.QCRMAxes.GridAlpha = 0.15;
            app.QCRMAxes.MinorGridLineStyle = '-';  % Solid lines for minor grid
            app.QCRMAxes.LineWidth = 0.5;      % Thin lines

            % Customize the plot
            app.QCRMAxes.XTick = 1:width(ratios);
            app.QCRMAxes.XTickLabel = ratios.Properties.VariableNames;
            app.QCRMAxes.XTickLabelRotation = 45;  % Rotate labels for better readability

            hold(app.QCRMAxes, 'off');

        end


        function update_results_table(app)

            % preallocate the columns
            spotnames = app.base.project.session.spot.name(:);  % ensure column vector
            spotNumbers = (1:numel(spotnames))';  % create column vector of numbers

            % Generate initial table with spot numbers and names
            T = table(spotNumbers, spotnames, 'VariableNames', {'#', 'Spotname'});

            if isequal(app.ShowDropDown.Value,'CPS')

                if isequal(app.DisplayDropDown.Value, 'Raw')

                    cps = app.base.project.session.spot.bkgd_corrected_cps;

                elseif isequal(app.DisplayDropDown.Value, 'Calibration line corrected')

                    if isequal(app.ISappliedDropDown.Value, 'No')
                        cps = app.base.project.session.spot.correction.calibration_line.corrected_bkgd_cps{app.BracketidDropDown_3.Value, app.selected_CAL};
                    elseif isequal(app.ISappliedDropDown.Value, 'Yes')

                        cps = app.base.project.session.spot.bkgd_corrected_cps;
                    end

                end

                T = [T, cps];

            elseif isequal(app.ShowDropDown.Value,'Mass Fractions')

                if isequal(app.DisplayDropDown.Value, 'Raw')
                    compositions = app.base.project.session.spot.matrix_composition{app.BracketidDropDown_2.Value, app.CalibratorDropDown_3.Value};
                    limits_of_detection = app.base.project.session.spot.matrix_limits_of_detection{app.BracketidDropDown_3.Value, app.selected_CAL};
                elseif isequal(app.DisplayDropDown.Value, 'Calibration line corrected')
                    compositions = app.base.project.session.spot.correction.calibration_line.corrected_matrix_composition{app.BracketidDropDown_2.Value, app.CalibratorDropDown_3.Value};

                    if isequal(app.ISappliedDropDown.Value, 'No')
                        limits_of_detection = app.base.project.session.spot.correction.calibration_line.corrected_matrix_limits_of_detection{app.BracketidDropDown_3.Value, app.selected_CAL};
                    elseif isequal(app.ISappliedDropDown.Value, 'Yes')
                        limits_of_detection = app.base.project.session.spot.matrix_limits_of_detection{app.BracketidDropDown_3.Value, app.selected_CAL};
                    end

                end

                % Convert tables to arrays for manipulation
                comp_array = table2array(compositions);
                lod_array = table2array(limits_of_detection);

                % Create logical mask for values below detection limit
                below_lod = comp_array < lod_array;

                % Create cell array for display values
                display_values = num2cell(comp_array);

                % Format detection limit values where needed
                display_values(below_lod) = arrayfun(@(x) ['<' num2str(x)], lod_array(below_lod), 'UniformOutput', false);

                % Convert back to table with original variable names
                display_compositions = array2table(display_values, 'VariableNames', compositions.Properties.VariableNames);

                % Combine with modified composition data
                T = [T, display_compositions];

                % Create mask for non-zero rows (check only composition columns)
                non_zero_mask = any(comp_array ~= 0, 2);

                % Filter table to keep only non-zero rows
                T = T(non_zero_mask, :);

            elseif isequal(app.ShowDropDown.Value,'Sensitivities')

                sensitivities = app.base.project.session.spot.sensitivities{app.BracketidDropDown_3.Value, app.selected_CAL};

                % Create mask for non-zero rows (check only composition columns)
                non_zero_mask = any(table2array(sensitivities) ~= 0, 2);

                % Filter table to keep only non-zero rows
                sensitivities = sensitivities(non_zero_mask, :);

                T = T(non_zero_mask, :);

                % Combine with modified composition data
                T = [T, sensitivities];

            elseif isequal(app.ShowDropDown.Value,'Limits of detection')


                if isequal(app.DisplayDropDown.Value, 'Raw')

                    lods = app.base.project.session.spot.matrix_limits_of_detection{app.BracketidDropDown_3.Value, app.selected_CAL};
                elseif isequal(app.DisplayDropDown.Value, 'Calibration line corrected')

                    if isequal(app.ISappliedDropDown.Value, 'No')
                        lods = app.base.project.session.spot.correction.calibration_line.corrected_matrix_limits_of_detection{app.BracketidDropDown_3.Value, app.selected_CAL};
                    elseif isequal(app.ISappliedDropDown.Value, 'Yes')
                        lods = app.base.project.session.spot.matrix_limits_of_detection{app.BracketidDropDown_3.Value, app.selected_CAL};
                    end

                end

                % Create mask for non-zero rows (check only composition columns)
                non_zero_mask = any(table2array(lods) ~= 0, 2);

                % Filter table to keep only non-zero rows
                lods = lods(non_zero_mask, :);

                T = T(non_zero_mask, :);

                % Combine with modified composition data
                T = [T, lods];

            end

            % Update the displayed table
            app.ResultsTable.Data = T;
            app.ResultsTable.ColumnName = T.Properties.VariableNames;

        end


        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% Calibration Line %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%


    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, sourceapp, project_name, project_id, session_id, data)

            if exist("sourceapp","var")
                app.sourceapp = sourceapp;
            end% input argument of startupfcn

            if exist("project_name", "var")
                app.base.project_name = project_name;
            end

            if  exist("data", "var") % e.g. for API call

                app.base.project.session =  app.sourceapp.base(project_id).project(session_id).session;
                app.base.project.session.config.module_status.correction = true;


            elseif exist("project_id","var") && exist("session_id","var") % coming from the base, GUI stuff

                app.base.project.session =  app.sourceapp.base(project_id).project(session_id).session;

            end

            app.project_id = project_id;
            app.session_id = session_id;
            app.UIFigure.Name = [char(app.base.project_name) ' - ' char(app.base.project.session.session_name)]; %string(app.base.project.session.session_name);


            if ~isfield(app.base.project.session.spot, 'correction') || ~isfield(app.base.project.session.spot.correction, 'calibration_line') || isempty(app.base.project.session.spot.correction.calibration_line.spot_to_fit)
                app.base.project.session.spot.correction.calibration_line.spot_to_fit = zeros(1, numel(app.base.project.session.spot.name))';
            end


            update_fit_tab(app)

        end

        % Menu selected function: SavetoBaseandExitMenu
        function SavetoBaseandExitMenuSelected(app, event)
            try

                app.base.project.session.config.module_status.correction = true;

                if app.editstatus == true
                    % uiconfirm ask user if finished
                    fig = app.UIFigure;
                    msg = "Do you want to proceed and save the data?";
                    title = "ICP-Base";
                    selection = uiconfirm(fig,msg,title, ...
                        "Options",["Save","Don't save","Cancel"], ...
                        "DefaultOption",1,"CancelOption",3);
                else
                    selection = 'Save';
                end

                switch selection
                    case 'Save'
                        [app.project_id, app.session_id] = save_to_base(app.sourceapp, app.project_id, app.base.project.session, app.session_id, app.base.project_name, settings);
                        delete(app)
                    case "Don't save"
                        delete(app)
                    case 'Cancel'
                        return
                end

            catch ME
                return
            end

        end

        % Value changed function: CalibratorDropDown_2, 
        % CalibratorDropDown_3
        function PrimaryReferenceMaterialDropDownValueChanged(app, event)
            app.CalibratorDropDown_2.Value = event.Source.Value;
            app.selected_CAL = app.CalibratorDropDown_2.Value;
            app.CalibratorDropDown_3.Value = event.Source.Value;


            update_results_and_quality_control(app)
        end

        % Value changed function: BracketidDropDown_2, BracketidDropDown_3
        function BracketIdDropDownValueChanged(app, event)
            app.BracketidDropDown_3.Value = event.Source.Value;
            app.selected_bracket_id = app.BracketidDropDown_3.Value;

            app.BracketidDropDown_2.Value = event.Source.Value;

            update_results_and_quality_control(app)
        end

        % Value changed function: QualityControlReferenceMaterialDropDown_2
        function QualityControlReferenceMaterialDropDown_2ValueChanged(app, event)
            app.selected_QCRM = app.QualityControlReferenceMaterialDropDown_2.Value;

            update_results_and_quality_control(app)
        end

        % Value changed function: ShowDropDown
        function ShowDropDownValueChanged(app, event)
            update_results_and_quality_control(app)

        end

        % Button pushed function: IncludeallQCRMButton
        function IncludeallQCRMButtonPushed(app, event)
            app.base.project.session.spot.correction.calibration_line.spot_to_fit = strcmp(app.base.project.session.spot.type, "QCRM");

            update_spot_table_colours(app)
            update_calibration_plot(app)
        end

        % Button pushed function: IncludeButton
        function IncludeButtonPushed2(app, event)
            app.base.project.session.spot.correction.calibration_line.spot_to_fit(app.selected_spot) = 1;

            update_spot_table_colours(app)
            update_calibration_plot(app)
        end

        % Callback function: SpotnameTable
        function SpotnameTableSelectionChanged2(app, event)

            app.selected_spot = unique(app.SpotnameTable.Selection(:,1));


        end

        % Button pushed function: ExcludeButton
        function ExcludeButtonPushed2(app, event)
            app.base.project.session.spot.correction.calibration_line.spot_to_fit(app.selected_spot) = 0;

            update_spot_table_colours(app)
            update_calibration_plot(app)
        end

        % Button pushed function: ResetButton_true
        function ResetButton_truePushed(app, event)
            app.base.project.session.spot.correction.calibration_line.spot_to_fit = zeros(1, numel(app.base.project.session.spot.name))';

            update_spot_table_colours(app)
        end

        % Value changed function: ISappliedDropDown
        function ISappliedDropDownValueChanged(app, event)

            update_calibration_plot(app)
        end

        % Value changed function: LogscaleCheckBox
        function LogscaleCheckBoxValueChanged(app, event)
            update_calibration_plot(app)
        end

        % Button pushed function: 
        % ApplycorrectionfromfitshowninplotButton_true
        function ApplycorrectionfromfitshowninplotButton_truePushed(app, event)

            apply_calibration_line_fit(app)


            update_results_and_quality_control(app)
        end

        % Value changed function: DisplayDropDown, DisplayDropDown_2
        function DisplayDropDownValueChanged(app, event)

            app.DisplayDropDown.Value = event.Source.Value;
            app.DisplayDropDown_2.Value = event.Source.Value;

            update_results_and_quality_control(app)
        end

        % Value changed function: AnalyteDropDown
        function AnalyteDropDownValueChanged(app, event)
            %value = app.AnalyteDropDown.Value;

            update_fit_tab(app)
            update_calibration_plot(app)
        end

        % Value changed function: PRMforFitDropDown
        function PRMforFitDropDownValueChanged(app, event)

            app.selected_CAL_for_fit = app.CALforFitDropDown.Value;

            update_fit_tab(app)
            update_calibration_plot(app)

        end

        % Value changed function: bracket_idDropDown
        function bracket_idDropDownValueChanged(app, event)
            %app.bracket_idDropDown.Value = event.Source.Value;

            update_calibration_plot(app)

        end

        % Button pushed function: ResetTableButton
        function ResetTableButtonPushed(app, event)

        end

        % Value changed function: QuadraticFitCheckBox
        function QuadraticFitCheckBoxValueChanged(app, event)
            %value = app.QuadraticFitCheckBox.Value;
            update_calibration_plot(app)
        end

        % Value changed function: CalibratorDropDown
        function CalibratorDropDownValueChanged(app, event)
            value = app.CalibratorDropDown.Value;
            
        end

        % Value changed function: QualityControlReferenceMaterialDropDown
        function QualityControlReferenceMaterialDropDownValueChanged(app, event)
            value = app.QualityControlReferenceMaterialDropDown.Value;
            
        end

        % Value changed function: BracketidDropDown
        function BracketidDropDownValueChanged(app, event)
            value = app.BracketidDropDown.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.BaseMenu = uimenu(app.UIFigure);
            app.BaseMenu.Text = 'Base';
            app.SavetoBaseandExitMenu = uimenu(app.BaseMenu);
            app.SavetoBaseandExitMenu.MenuSelectedFcn = createCallbackFcn(app, @SavetoBaseandExitMenuSelected, true);
            app.SavetoBaseandExitMenu.Text = 'Save to Base and Exit';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.CalibrationlineTab = uitab(app.TabGroup);
            app.CalibrationlineTab.Title = 'Calibration line';
            app.TabGroup2 = uitabgroup(app.CalibrationlineTab);
            app.FitTab = uitab(app.TabGroup2);
            app.FitTab.Title = 'Fit';
            app.CalibrationAxes = uiaxes(app.FitTab);
            xlabel(app.CalibrationAxes, 'True')
            ylabel(app.CalibrationAxes, 'Measured')
            zlabel(app.CalibrationAxes, 'Z')
            app.CalibrationAxes.XScale = 'log';
            app.CalibrationAxes.YScale = 'log';
            app.ApplycorrectionfromfitshowninplotButton_true = uibutton(app.FitTab, 'push');
            app.ApplycorrectionfromfitshowninplotButton_true.ButtonPushedFcn = createCallbackFcn(app, @ApplycorrectionfromfitshowninplotButton_truePushed, true);
            app.ApplycorrectionfromfitshowninplotButton_true.Text = 'Apply correction';
            app.ResetButton_true = uibutton(app.FitTab, 'push');
            app.ResetButton_true.ButtonPushedFcn = createCallbackFcn(app, @ResetButton_truePushed, true);
            app.ResetButton_true.Text = 'Reset';
            app.IncludeallQCRMButton = uibutton(app.FitTab, 'push');
            app.IncludeallQCRMButton.ButtonPushedFcn = createCallbackFcn(app, @IncludeallQCRMButtonPushed, true);
            app.IncludeallQCRMButton.Text = 'Include all QCRM';
            app.ExcludeButton = uibutton(app.FitTab, 'push');
            app.ExcludeButton.ButtonPushedFcn = createCallbackFcn(app, @ExcludeButtonPushed2, true);
            app.ExcludeButton.Text = 'Exclude';
            app.IncludeButton = uibutton(app.FitTab, 'push');
            app.IncludeButton.ButtonPushedFcn = createCallbackFcn(app, @IncludeButtonPushed2, true);
            app.IncludeButton.Text = 'Include';
            app.SpotnameTable = uitable(app.FitTab);
            app.SpotnameTable.ColumnName = '';
            app.SpotnameTable.RowName = {};
            app.SpotnameTable.SelectionChangedFcn = createCallbackFcn(app, @SpotnameTableSelectionChanged2, true);
            app.Label = uilabel(app.FitTab);
            app.Label.Text = 'If there were three spotsizes e.g. 20, 38, 80 give the spots the bracket_ids 1,2,3';
            app.PRMLabel = uilabel(app.FitTab);
            app.PRMLabel.Text = 'CAL';
            app.PRMforFitDropDown = uidropdown(app.FitTab);
            app.PRMforFitDropDown.Items = {};
            app.PRMforFitDropDown.ValueChangedFcn = createCallbackFcn(app, @PRMforFitDropDownValueChanged, true);
            app.PRMforFitDropDown.Value = {};
            app.ISappliedDropDown = uidropdown(app.FitTab);
            app.ISappliedDropDown.Items = {'Yes', 'No'};
            app.ISappliedDropDown.ValueChangedFcn = createCallbackFcn(app, @ISappliedDropDownValueChanged, true);
            app.ISappliedDropDown.Value = 'Yes';
            app.bracket_idDropDownLabel = uilabel(app.FitTab);
            app.bracket_idDropDownLabel.Text = 'bracket_id';
            app.bracket_idDropDown = uidropdown(app.FitTab);
            app.bracket_idDropDown.Items = {'All'};
            app.bracket_idDropDown.ValueChangedFcn = createCallbackFcn(app, @bracket_idDropDownValueChanged, true);
            app.bracket_idDropDown.Value = 'All';
            app.LogscaleCheckBox = uicheckbox(app.FitTab);
            app.LogscaleCheckBox.ValueChangedFcn = createCallbackFcn(app, @LogscaleCheckBoxValueChanged, true);
            app.LogscaleCheckBox.Text = 'Log-scale';
            app.x2Label_2 = uilabel(app.FitTab);
            app.x2Label_2.Text = 'x^2 +';
            app.x2EditField = uieditfield(app.FitTab, 'numeric');
            app.xLabel_2 = uilabel(app.FitTab);
            app.xLabel_2.Text = 'x +';
            app.xEditField = uieditfield(app.FitTab, 'numeric');
            app.cEditField = uieditfield(app.FitTab, 'numeric');
            app.ContainsEditField_2Label = uilabel(app.FitTab);
            app.ContainsEditField_2Label.Text = 'Contains:';
            app.ContainsEditField = uieditfield(app.FitTab, 'text');
            app.AnalyteDropDown = uidropdown(app.FitTab);
            app.AnalyteDropDown.Items = {};
            app.AnalyteDropDown.ValueChangedFcn = createCallbackFcn(app, @AnalyteDropDownValueChanged, true);
            app.AnalyteDropDown.Value = {};
            app.IntStdappliedLabel = uilabel(app.FitTab);
            app.IntStdappliedLabel.Text = 'Int. Std. applied';
            app.QuadraticFitCheckBox = uicheckbox(app.FitTab);
            app.QuadraticFitCheckBox.ValueChangedFcn = createCallbackFcn(app, @QuadraticFitCheckBoxValueChanged, true);
            app.QuadraticFitCheckBox.Text = 'Quadratic Fit';
            app.CorrectedDataTab = uitab(app.TabGroup2);
            app.CorrectedDataTab.Title = 'Corrected Data';
            app.GridLayout2 = uigridlayout(app.CorrectedDataTab);
            app.ResultsTable = uitable(app.GridLayout2);
            app.ResultsTable.ColumnName = '';
            app.ResultsTable.RowName = {};
            app.CreateOutputTableButton = uibutton(app.GridLayout2, 'push');
            app.CreateOutputTableButton.Text = 'Create Output Table';
            app.CalibratorDropDown_2Label = uilabel(app.GridLayout2);
            app.CalibratorDropDown_2Label.Text = 'Calibrator';
            app.CalibratorDropDown_2 = uidropdown(app.GridLayout2);
            app.CalibratorDropDown_2.Items = {};
            app.CalibratorDropDown_2.ValueChangedFcn = createCallbackFcn(app, @PrimaryReferenceMaterialDropDownValueChanged, true);
            app.CalibratorDropDown_2.Value = {};
            app.BracketidDropDown_3Label = uilabel(app.GridLayout2);
            app.BracketidDropDown_3Label.Text = 'Bracket id';
            app.BracketidDropDown_3 = uidropdown(app.GridLayout2);
            app.BracketidDropDown_3.Items = {};
            app.BracketidDropDown_3.ValueChangedFcn = createCallbackFcn(app, @BracketIdDropDownValueChanged, true);
            app.BracketidDropDown_3.Value = {};
            app.ShowDropDownLabel = uilabel(app.GridLayout2);
            app.ShowDropDownLabel.Text = 'Show';
            app.ShowDropDown = uidropdown(app.GridLayout2);
            app.ShowDropDown.Items = {'Mass Fractions', 'Sensitivities', 'CPS', 'Limits of detection'};
            app.ShowDropDown.ValueChangedFcn = createCallbackFcn(app, @ShowDropDownValueChanged, true);
            app.ShowDropDown.Value = 'Mass Fractions';
            app.DisplayDropDownLabel = uilabel(app.GridLayout2);
            app.DisplayDropDownLabel.Text = 'Display';
            app.DisplayDropDown = uidropdown(app.GridLayout2);
            app.DisplayDropDown.Items = {'Raw', 'Calibration line corrected'};
            app.DisplayDropDown.ValueChangedFcn = createCallbackFcn(app, @DisplayDropDownValueChanged, true);
            app.DisplayDropDown.Value = 'Raw';
            app.ResetTableButton = uibutton(app.GridLayout2, 'push');
            app.ResetTableButton.ButtonPushedFcn = createCallbackFcn(app, @ResetTableButtonPushed, true);
            app.ResetTableButton.Text = 'Reset Table';
            app.QualityControlofCorrectedDataTab = uitab(app.TabGroup2);
            app.QualityControlofCorrectedDataTab.Title = 'Quality Control of Corrected Data';
            app.SRMAxes = uiaxes(app.QualityControlofCorrectedDataTab);
            xlabel(app.SRMAxes, 'Analyte')
            ylabel(app.SRMAxes, 'Measured Values/Preferred Values')
            zlabel(app.SRMAxes, 'Z')
            app.CalibratorDropDown_3Label = uilabel(app.QualityControlofCorrectedDataTab);
            app.CalibratorDropDown_3Label.Text = 'Calibrator';
            app.CalibratorDropDown_3 = uidropdown(app.QualityControlofCorrectedDataTab);
            app.CalibratorDropDown_3.Items = {};
            app.CalibratorDropDown_3.ValueChangedFcn = createCallbackFcn(app, @PrimaryReferenceMaterialDropDownValueChanged, true);
            app.CalibratorDropDown_3.Value = {};
            app.BracketidDropDown_2Label = uilabel(app.QualityControlofCorrectedDataTab);
            app.BracketidDropDown_2Label.Text = 'Bracket id';
            app.BracketidDropDown_2 = uidropdown(app.QualityControlofCorrectedDataTab);
            app.BracketidDropDown_2.Items = {};
            app.BracketidDropDown_2.ValueChangedFcn = createCallbackFcn(app, @BracketIdDropDownValueChanged, true);
            app.BracketidDropDown_2.Value = {};
            app.QualityControlReferenceMaterialDropDown_2Label = uilabel(app.QualityControlofCorrectedDataTab);
            app.QualityControlReferenceMaterialDropDown_2Label.Text = 'Quality Control Reference Material';
            app.QualityControlReferenceMaterialDropDown_2 = uidropdown(app.QualityControlofCorrectedDataTab);
            app.QualityControlReferenceMaterialDropDown_2.Items = {};
            app.QualityControlReferenceMaterialDropDown_2.ValueChangedFcn = createCallbackFcn(app, @QualityControlReferenceMaterialDropDown_2ValueChanged, true);
            app.QualityControlReferenceMaterialDropDown_2.Value = {};
            app.DisplayDropDown_2Label = uilabel(app.QualityControlofCorrectedDataTab);
            app.DisplayDropDown_2Label.Text = 'Display';
            app.DisplayDropDown_2 = uidropdown(app.QualityControlofCorrectedDataTab);
            app.DisplayDropDown_2.Items = {'Raw', 'Calibration line corrected'};
            app.DisplayDropDown_2.ValueChangedFcn = createCallbackFcn(app, @DisplayDropDownValueChanged, true);
            app.DisplayDropDown_2.Value = 'Raw';
            app.QCRMcorrectionTab = uitab(app.TabGroup);
            app.QCRMcorrectionTab.Title = 'QCRM correction';
            app.TabGroup3 = uitabgroup(app.QCRMcorrectionTab);
            app.PlotTab = uitab(app.TabGroup3);
            app.PlotTab.Title = 'Plot';
            app.SRMAxes_2 = uiaxes(app.PlotTab);
            xlabel(app.SRMAxes_2, 'Analyte')
            ylabel(app.SRMAxes_2, 'Measured Values/Preferred Values')
            zlabel(app.SRMAxes_2, 'Z')
            app.PrimaryReferenceMaterialDropDown_3Label_2 = uilabel(app.PlotTab);
            app.PrimaryReferenceMaterialDropDown_3Label_2.Text = 'Calibrator';
            app.CalibratorDropDown = uidropdown(app.PlotTab);
            app.CalibratorDropDown.Items = {};
            app.CalibratorDropDown.ValueChangedFcn = createCallbackFcn(app, @CalibratorDropDownValueChanged, true);
            app.CalibratorDropDown.Value = {};
            app.BracketidDropDownLabel = uilabel(app.PlotTab);
            app.BracketidDropDownLabel.Text = 'Bracket id';
            app.BracketidDropDown = uidropdown(app.PlotTab);
            app.BracketidDropDown.Items = {};
            app.BracketidDropDown.ValueChangedFcn = createCallbackFcn(app, @BracketidDropDownValueChanged, true);
            app.BracketidDropDown.Value = {};
            app.QualityControlReferenceMaterialDropDownLabel = uilabel(app.PlotTab);
            app.QualityControlReferenceMaterialDropDownLabel.Text = 'Quality Control Reference Material';
            app.QualityControlReferenceMaterialDropDown = uidropdown(app.PlotTab);
            app.QualityControlReferenceMaterialDropDown.Items = {};
            app.QualityControlReferenceMaterialDropDown.ValueChangedFcn = createCallbackFcn(app, @QualityControlReferenceMaterialDropDownValueChanged, true);
            app.QualityControlReferenceMaterialDropDown.Value = {};
            app.NotimplementedyetLabel = uilabel(app.PlotTab);
            app.NotimplementedyetLabel.Text = 'Not implemented yet';
            app.CorrectedDataTab_2 = uitab(app.TabGroup3);
            app.CorrectedDataTab_2.Title = 'Corrected Data';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Correction_Module(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end