classdef Downhole_Correction_Module < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        BaseMenu                      matlab.ui.container.Menu
        SaveSessiontoBaseandExitMenu  matlab.ui.container.Menu
        GridLayout                    matlab.ui.container.GridLayout
        FitDropDown                   matlab.ui.control.DropDown
        FitDropDownLabel              matlab.ui.control.Label
        RatioDropDown                 matlab.ui.control.DropDown
        RatioDropDownLabel            matlab.ui.control.Label
        BracketidDropDown             matlab.ui.control.DropDown
        BracketidDropDownLabel        matlab.ui.control.Label
        CalibratorDropDown            matlab.ui.control.DropDown
        CalibratorDropDownLabel       matlab.ui.control.Label
        ProgressPanel                 matlab.ui.container.Panel
        ResultsButton                 matlab.ui.control.Button
        QuantificationButton          matlab.ui.control.Button
        DriftButton                   matlab.ui.control.Button
        ClassificationButton          matlab.ui.control.Button
        ImportButton                  matlab.ui.control.Button
        IntervalButton                matlab.ui.control.Button
        ProceedtoQuantificationModuleButton  matlab.ui.control.Button
        RatioAxes                     matlab.ui.control.UIAxes
        ResidualAxes                  matlab.ui.control.UIAxes
    end


%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%                     %%%%%%%%%%%%%%%%%%%%%%%%%                        %
%                     %%%      ICP-Base     %%%                        %
%                     %%%%%%%%%%%%%%%%%%%%%%%%%                        %
%                                                                      %
%  Copyright (C) 2026 Sebastian Stumpf                                 %
%                                                                      %
%  This program is free software: you can redistribute it and/or       %
%  modify it under the terms of the GNU General Public License as      %
%  published by the Free Software Foundation, either version 3 of      %
%  the License, or any later version.                                  %
%                                                                      %
%  This program is distributed in the hope that it will be useful,     %
%  but WITHOUT ANY WARRANTY; without even the implied warranty of      %
%  MERCHANTABILITY or FITNESS FOR A PARTICULAR PURPOSE. See the        %
%  GNU General Public License for more details.                        %
%                                                                      %
%  You should have received a copy of the GNU General Public License   %
%  along with this program. If not, see <https://www.gnu.org/licenses/>%
%                                                                      %
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

    properties (Access = public)
        base
        project_id
        session_id
    end

    properties (Access = private)
        sourceapp

        all_CAL
        selected_CAL
        selected_bracket_id

        selected_ratio


    end

    methods (Access = private)

        function update_downhole_module(app)

            % Get indices where type is "CAL"
            cal_indices = strcmp(app.base.project.session.spot.type, "CAL");

            % Get reference materials only for CAL spots
            cal_materials = app.base.project.session.spot.referencematerial(cal_indices);

            % Get unique names, excluding "None" and "Select Reference Material"
            unique_names = unique(cal_materials);
            unique_names = unique_names(~strcmp(unique_names, 'None'));
            validCALs = unique_names(~strcmp(unique_names, 'Select Reference Material'));

            % Populate CAL dropdown
            app.CalibratorDropDown.Items = validCALs;
            app.CalibratorDropDown.ItemsData = 1:numel(app.CalibratorDropDown.Items);

            % Get selected reference material and find matching indices
            selectedRM = validCALs(app.CalibratorDropDown.Value);
            rmIndices = strcmp(app.base.project.session.spot.referencematerial, selectedRM);

            % Filter bracket IDs for selected reference material
            filteredBracketIds = app.base.project.session.spot.bracket_id(rmIndices);
            uniqueBracketIds = unique(filteredBracketIds);
            uniqueBracketIds = uniqueBracketIds(uniqueBracketIds ~= 0);

            % Update bracket ID dropdown
            app.BracketidDropDown.Items = string(uniqueBracketIds);
            app.BracketidDropDown.ItemsData = uniqueBracketIds;


            % at startup the selected_CAL is empty, make it 1 so that
            % something is plotted anyways
            if isempty(app.selected_CAL)
                app.selected_CAL = 1;
            end
            % Set default bracket_id if empty - use first available bracket_id
            if isempty(app.selected_bracket_id)
                app.selected_bracket_id = uniqueBracketIds(1);
                app.BracketidDropDown.Value = uniqueBracketIds(1);
            end

            % Check which ratios app.selected_CAL has and which were
            % measured and fill the dropdown with those
            % Find the index of the selected RM in the global loaded structures
            global_rm_idx = find(strcmp(app.base.project.session.referencematerials.names, selectedRM));

            if ~isempty(global_rm_idx)
                % Extract the ratio table for this specific reference material
                rm_ratio_table = app.base.project.session.referencematerials.ratio{global_rm_idx};

                if ~isempty(rm_ratio_table)
                    % Extract the exact ratio strings (e.g., '206Pb/238U')
                    all_ratios = rm_ratio_table.Properties.VariableNames;
                    available_ratios = {};

                    % The measured analytes (e.g., {'Pb206', 'U238'})
                    analytes = app.base.project.session.spot.analytes;

                    % Filter out ratios that don't have measured analytes
                    for k = 1:length(all_ratios)
                        ratio_str = all_ratios{k};
                        parts = split(ratio_str, '/');

                        if length(parts) == 2
                            % Extract numbers and letters using regex
                            % Groups: 1=leading numbers, 2=letters, 3=trailing numbers
                            matchNum = regexp(parts{1}, '([0-9]*)([a-zA-Z]+)([0-9]*)', 'tokens', 'once');
                            matchDen = regexp(parts{2}, '([0-9]*)([a-zA-Z]+)([0-9]*)', 'tokens', 'once');

                            if ~isempty(matchNum) && ~isempty(matchDen)
                                % Reconstruct as {Element}{Mass} format (e.g., 'Pb' + '206')
                                req_num = [matchNum{2}, matchNum{1}, matchNum{3}];
                                req_den = [matchDen{2}, matchDen{1}, matchDen{3}];

                                % Both the numerator and denominator must be in the analytes list
                                if ismember(req_num, analytes) && ismember(req_den, analytes)
                                    available_ratios{end+1} = ratio_str;
                                end
                            end
                        end
                    end

                    if ~isempty(available_ratios)
                        % Update the RatioDropDown Items with ONLY the possible ratios
                        app.RatioDropDown.Items = available_ratios;

                        % Set a default selected ratio
                        if isempty(app.RatioDropDown.Value) || ~ismember(app.RatioDropDown.Value, available_ratios)
                            app.RatioDropDown.Value = available_ratios{1};
                        end
                    end
                end
            end


            plot_all_ratios(app)


        end


        function update_progress_buttons(app)

            status = app.base.project.session.config.module_status;

            green = [204/255, 1, 204/255];
            orange = [1, 229/255, 204/255];
            red = [1, 204/255, 204/255];

            if isfield(status, 'import') && status.import == true
                app.ImportButton.Enable = "on";
                app.ImportButton.BackgroundColor = green;
            else
                app.ImportButton.Enable = "off";
                app.ImportButton.BackgroundColor = red;
            end

            if isfield(status, 'interval') && status.interval == true
                app.IntervalButton.Enable = "on";
                app.IntervalButton.BackgroundColor = green;
            else
                app.IntervalButton.Enable = "off";
                app.IntervalButton.BackgroundColor = red;
            end

            if isfield(status, 'classification') && status.classification == true
                app.ClassificationButton.Enable = "on";
                app.ClassificationButton.BackgroundColor = green;
            else
                app.ClassificationButton.Enable = "off";
                app.ClassificationButton.BackgroundColor = red;
            end

            app.DriftButton.Enable = "on";
            app.DriftButton.BackgroundColor = green;
            app.DriftButton.FontWeight = 'bold';

            if isfield(status, 'quantification') && status.quantification == true
                app.QuantificationButton.Enable = "on";
                app.QuantificationButton.BackgroundColor = green;
            else
                app.QuantificationButton.Enable = "off";
                app.QuantificationButton.BackgroundColor = red;
            end

            if isfield(status, 'results') && status.results == true
                app.ResultsButton.Enable = "on";
                app.ResultsButton.BackgroundColor = green;
            else
                app.ResultsButton.Enable = "off";
                app.ResultsButton.BackgroundColor = red;
            end

        end

        % Custom function to center the figure
        function center_figure(app, hfig)
            try
                screenSize = get(0,'ScreenSize');
                figPos = hfig.Position;
                figWidth = figPos(3);
                figHeight = figPos(4);
                newX = (screenSize(3) - figWidth) / 2;
                newY = (screenSize(4) - figHeight) / 2;
                hfig.Position=[newX, newY, figWidth, figHeight];
                figure(hfig)
            catch
                ME
                disp('CenterFig_fun failed!')
                disp(ME.message)
            end
        end


        %%%%%% these functions actually do the downhole correction %%%%%%%%

        function calculate_background_corrected_matrix_intensities(app)

            background_start = app.base.project.session.spot.background_starts;
            background_end = app.base.project.session.spot.background_ends;

            % calculate the mean for the matrix
            matrix_1_start = app.base.project.session.spot.matrix_1_starts;
            matrix_1_end = app.base.project.session.spot.matrix_1_ends;

            matrix_2_start = app.base.project.session.spot.matrix_2_starts;
            matrix_2_end = app.base.project.session.spot.matrix_2_ends;

            matrix_3_start = app.base.project.session.spot.matrix_3_starts;
            matrix_3_end = app.base.project.session.spot.matrix_3_ends;

            % case of full measurement input
            if isequal(app.base.project.session.config.datatype, 'Continuous')
                signal = app.base.project.session.spot.signal_data;
            end

            % Initialize bg_corrected_cps_matrix as numeric array
            bg_corr_matrix = single(zeros(length(app.base.project.session.spot.signal_starts), numel(app.base.project.session.spot.analytes)));

            for spot = 1:numel(app.base.project.session.spot.signal_starts)

                % case of single_spot input
                if isequal(app.base.project.session.config.datatype, 'Single')
                    signal = app.base.project.session.spot.signal_data{spot};
                end

                % calculate the average of the background of every UNK
                if ~isempty(background_start{spot}) || ~isempty(background_end{spot})
                    mean_analytes_cps_background = mean(table2array(signal(background_start{spot}:background_end{spot}, 2:end)), 1);

                else
                    continue
                end

                % Collect data from all matrix intervals
                all_matrix_data = [];
                if ~isempty(matrix_1_start{spot}) && ~isempty(matrix_1_end{spot})
                    all_matrix_data = [all_matrix_data; table2array(signal(matrix_1_start{spot}:matrix_1_end{spot}, 2:end))];
                end
                if ~isempty(matrix_2_start{spot}) && ~isempty(matrix_2_end{spot})
                    all_matrix_data = [all_matrix_data; table2array(signal(matrix_2_start{spot}:matrix_2_end{spot}, 2:end))];
                end
                if ~isempty(matrix_3_start{spot}) && ~isempty(matrix_3_end{spot})
                    all_matrix_data = [all_matrix_data; table2array(signal(matrix_3_start{spot}:matrix_3_end{spot}, 2:end))];
                end

                if ~isempty(all_matrix_data)
                    % Perform background correction
                    matrix_data_corrected = all_matrix_data - mean_analytes_cps_background;
                    matrix_data_corrected = max(matrix_data_corrected, 0);
                    % Calculate the simple mean
                    mean_analytes_cps_matrix = mean(matrix_data_corrected, 1);
                else
                    continue
                end

                bg_corr_matrix(spot, :) = mean_analytes_cps_matrix;

                % Convert to table again
                app.base.project.session.spot.bkgd_corrected_cps =  array2table(bg_corr_matrix, 'VariableNames', signal.Properties.VariableNames(2:end));

            end
        end
 
        function group_CALs_and_bracket_ids(app)
            % Get indices where type is "CAL"
            cal_indices = strcmp(app.base.project.session.spot.type, "CAL");

            % Get reference materials only for CAL spots
            cal_materials = app.base.project.session.spot.referencematerial(cal_indices);

            % Get unique names, excluding "None" and "Select Reference Material"
            unique_names = unique(cal_materials);
            unique_names = unique_names(~strcmp(unique_names, 'None'));
            unique_names = unique_names(~strcmp(unique_names, 'Select Reference Material'));

            % Get all unique bracket IDs
            all_bracket_ids = unique(app.base.project.session.spot.bracket_id);
            all_bracket_ids = all_bracket_ids(all_bracket_ids ~= 0);

            % Initialize storage structure
            app.sensitivities_to_fit = cell(max(all_bracket_ids), length(unique_names));

            % Iterate through each unique name
            for cal_idx = 1:length(unique_names)
                name = unique_names{cal_idx};
                indices = [];

                % Find indices matching current name
                for j = 1:length(app.base.project.session.spot.referencematerial)
                    if strcmp(app.base.project.session.spot.referencematerial{j}, name) && strcmp(app.base.project.session.spot.type{j}, "CAL")
                        indices(end+1) = j;
                    end
                end

                % Process brackets for this CAL
                bracket_ids = app.base.project.session.spot.bracket_id(indices);
                unique_bracket_ids = unique(bracket_ids);

                %Process each bracket
                for bracket_idx = 1:length(unique_bracket_ids)
                    bracket_id = unique_bracket_ids(bracket_idx);
                    bracket_indices = indices(bracket_ids == bracket_id);

                    % If no intervals for a given CAL were set it has to be
                    % disregarded here, otherwise the sensitivities are all
                    % just 0 which will destroy the average.
                    % Filter out indices where all sensitivities are zero
                    bracket_indices = bracket_indices(any(app.initial_sensitivities(bracket_indices, :), 2));

                    % Skip if no valid indices remain
                    if isempty(bracket_indices)
                        message = "Too many CAL intervals are missing to regress sensitivities. ICP-Base needs a bracket start and end to do the regression between them.";
                        uialert(app.UIFigure,message,"Warning", ...
                            "Icon","warning");
                        disp("Too many CAL intervals are missing for a bracket")
                        return
                    end

                    % Get sensitivities of all bracket_ids that do not have
                    % zero sensitivity
                    sensitivities_to_average = zeros(numel(bracket_indices), size(app.initial_sensitivities, 2));
                    for x = 1:numel(bracket_indices)
                        sensitivities_to_average(x, :) = app.initial_sensitivities(bracket_indices(x), :);
                    end

                    % Process groups within bracket
                    gaps = diff(bracket_indices);
                    group_breaks = find(gaps > 2);
                    group_breaks = [0; group_breaks(:); length(bracket_indices)];

                    average_sensitivities_to_fit = zeros(size(sensitivities_to_average));
                    average_sensitivities_to_fit_idx = zeros(length(bracket_indices), 1);

                    for group_idx = 1:length(group_breaks)-1
                        start_idx = group_breaks(group_idx) + 1;
                        end_idx = group_breaks(group_idx+1);
                        current_group = start_idx:end_idx;

                        % Calculate averages
                        group_sensitivities = sensitivities_to_average(current_group, :);
                        avg_sensitivity = mean(group_sensitivities, 1,'omitnan');
                        current_bracket_indices = bracket_indices(current_group);

                        % Calculate storage position
                        if mod(length(current_group), 2) == 0
                            mid_idx = length(current_group)/2 - 0.5;  % Subtract 0.5 to get the correct middle position
                            storage_idx = current_group(ceil(mid_idx));
                            plot_position = (current_bracket_indices(ceil(mid_idx)) + current_bracket_indices(ceil(mid_idx) + 1)) / 2;
                        else
                            mid_idx = ceil(length(current_group)/2);
                            storage_idx = current_group(mid_idx);
                            plot_position = current_bracket_indices(mid_idx);
                        end

                        average_sensitivities_to_fit(storage_idx, :) = avg_sensitivity;
                        average_sensitivities_to_fit_idx(storage_idx) = plot_position;
                    end

                    % Remove zero rows
                    non_zero_rows = ~all(average_sensitivities_to_fit == 0, 2);
                    average_sensitivities_to_fit = average_sensitivities_to_fit(non_zero_rows, :);
                    average_sensitivities_to_fit_idx = average_sensitivities_to_fit_idx(non_zero_rows);

                    %%%%%%% Store results in app structure %%%%%%%
                    app.sensitivities_to_fit{bracket_id, cal_idx} = struct(...
                        'sensitivities', average_sensitivities_to_fit, ...
                        'indices', average_sensitivities_to_fit_idx);
                end
            end
        end


        %%%%%%% functions for GUI plotting %%%%%%%%%%

        function plot_all_ratios(app)

            % Get the chosen ratio from the dropdown
            chosen_ratio = string(app.RatioDropDown.Value);

            % Check if there is actually a valid ratio to plot
            if isempty(chosen_ratio) || chosen_ratio == "No Ratios Available" || chosen_ratio == "No Valid Ratios"
                cla(app.RatioAxes);
                return;
            end

            % Parse the ratio to get the column names in {Element}{Mass} format
            parts = split(chosen_ratio, '/');
            if length(parts) == 2
                matchNum = regexp(parts{1}, '([0-9]*)([a-zA-Z]+)([0-9]*)', 'tokens', 'once');
                matchDen = regexp(parts{2}, '([0-9]*)([a-zA-Z]+)([0-9]*)', 'tokens', 'once');

                if ~isempty(matchNum) && ~isempty(matchDen)
                    % Reconstruct to match analytes (e.g. 'Pb206' and 'U238')
                    req_num = [matchNum{2}, matchNum{1}, matchNum{3}];
                    req_den = [matchDen{2}, matchDen{1}, matchDen{3}];
                else
                    return; % If regex fails, exit
                end
            else
                return; % If there isn't exactly one '/', exit
            end

            % 1. Get the Selected Reference Material name safely
            if isnumeric(app.CalibratorDropDown.Value) && ~isempty(app.CalibratorDropDown.Items)
                selectedRM = string(app.CalibratorDropDown.Items(app.CalibratorDropDown.Value));
            else
                selectedRM = string(app.CalibratorDropDown.Value);
            end

            % 2. Get the Selected Bracket ID safely
            if ischar(app.BracketidDropDown.Value) || isstring(app.BracketidDropDown.Value)
                selectedBracket = str2double(app.BracketidDropDown.Value);
            else
                selectedBracket = app.BracketidDropDown.Value;
            end

            % 3. Create logical masks for our 3 criteria
            is_cal = strcmp(app.base.project.session.spot.type, "CAL");
            is_rm = strcmp(app.base.project.session.spot.referencematerial, selectedRM);
            is_bracket = (app.base.project.session.spot.bracket_id == selectedBracket);

            % Get the spot indices that match ALL THREE criteria
            app.all_CAL = find(is_cal & is_rm & is_bracket);

            % If no matching spots exist for this combination, clear axes and exit
            if isempty(app.all_CAL)
                cla(app.RatioAxes);
                return;
            end

            % Get the signal starts and ends of these specific matched spots
            signal_starts = app.base.project.session.spot.signal_starts(app.all_CAL);
            signal_ends = app.base.project.session.spot.signal_ends(app.all_CAL);

            % Store config and data structurally
            datatype = app.base.project.session.config.datatype;
            all_signal_data = app.base.project.session.spot.signal_data;

            % Prepare the axes (clears any previous plots and allows overlays)
            cla(app.RatioAxes);
            hold(app.RatioAxes, 'on');

            % Create an empty cell array to collect signals for averaging
            collected_ratios = cell(length(app.all_CAL), 1);

            % Loop through each matched CAL spot to extract and plot
            for i = 1:length(app.all_CAL)

                cur_start = signal_starts(i);
                cur_end = signal_ends(i);

                try
                    % Extract the specific numerator and denominator columns
                    if strcmp(datatype, 'Continuous')
                        num_signal = all_signal_data{cur_start:cur_end, req_num};
                        den_signal = all_signal_data{cur_start:cur_end, req_den};

                    elseif strcmp(datatype, 'Single')
                        spot_idx = app.all_CAL(i);
                        spot_table = all_signal_data{spot_idx};
                        num_signal = spot_table{cur_start:cur_end, req_num};
                        den_signal = spot_table{cur_start:cur_end, req_den};
                    else
                        continue;
                    end

                    % Calculate the ratio line for this specific spot
                    ratio_signal = num_signal ./ den_signal;

                    % Plot the individual ratio (Optional: color slightly grey/transparent if you want)
                    plot(app.RatioAxes, ratio_signal);

                    % Store in our collection array
                    collected_ratios{i} = ratio_signal;

                catch
                    % If the table indexing fails (e.g., column doesn't exist), skip to the next
                    continue;
                end
            end

            % --- Calculate and plot the average ---
            % Find lengths of all extracted data to handle uneven signal durations safely
            lengths = cellfun(@length, collected_ratios);
            if any(lengths > 0)
                max_len = max(lengths);
                padded_matrix = NaN(length(collected_ratios), max_len);

                % Pad all signals with NaNs at the end so they fit into one matrix
                for j = 1:length(collected_ratios)
                    if ~isempty(collected_ratios{j})
                        padded_matrix(j, 1:lengths(j)) = collected_ratios{j};
                    end
                end

                % Compute column-wise mean, automatically skipping NaNs
                mean_signal = mean(padded_matrix, 1, 'omitnan');

                % Plot the average as a thick red line
                plot(app.RatioAxes, mean_signal, 'r-', 'LineWidth', 3);
            end

            % Label the Y-axis using the chosen ratio string (e.g. '206Pb/238U')
            ylabel(app.RatioAxes, chosen_ratio);

            % Turn hold off so future plots start fresh
            hold(app.RatioAxes, 'off');

        end


    end


    methods (Access = public)


    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, sourceapp, project_name, project_id, session_id, data)
            center_figure(app, app.UIFigure)


            if exist("sourceapp","var")
                app.sourceapp = sourceapp;
            end% input argument of startupfcn

            if exist("project_name", "var")
                app.base.project_name = project_name;
            end

            if  exist("data", "var") % e.g. for API call

                app.base.project.session =  app.sourceapp.base(project_id).project(session_id).session;
                app.base.project.session.config.module_status.downhole = true;


            elseif exist("project_id","var") && exist("session_id","var") % coming from the base, GUI stuff

                app.base.project.session =  app.sourceapp.base(project_id).project(session_id).session;

            end

            app.project_id = project_id;
            app.session_id = session_id;
            app.UIFigure.Name = [char(app.base.project_name) ' - ' char(app.base.project.session.session_name)]; %string(app.base.project.session.session_name);

            % Change "PRM" to "CAL" & "SRM" to "QCRM"-Backwards compatibility
            app.base.project.session.spot.type(app.base.project.session.spot.type == "PRM") = "CAL";
            app.base.project.session.spot.type(app.base.project.session.spot.type == "SRM") = "QCRM";
            if isfield(app.base.project.session.spot, 'prm_sensitivities')
                % Copy data to the new field
                [app.base.project.session.spot.cal_sensitivities] = app.base.project.session.spot.prm_sensitivities;
                % Remove the old field
                app.base.project.session.spot = rmfield(app.base.project.session.spot, 'prm_sensitivities');
            end
            

            app.base.project.session.config.module_status.downhole = true;

            update_downhole_module(app)
            update_progress_buttons(app)
        end

        % Value changed function: CalibratorDropDown
        function CalibratorDropDownValueChanged(app, event)
            app.selected_CAL = app.CalibratorDropDown.Value;

            update_downhole_module(app)
        end

        % Button pushed function: ProceedtoQuantificationModuleButton
        function ProceedtoQuantificationModuleButtonPushed2(app, event)
         update_downhole_module(app)
           plot_all_ratios(app)
            
            % here this is not necessary because nothing can be changed
         % anyways

            % try

                %app.base.project.session.config.module_status.downhole = true;

                % % uiconfirm ask user if finished
                % fig = app.UIFigure;
                % msg = "Do you want to save the data?";
                % title = "ICP-Base";
                % selection = uiconfirm(fig,msg,title, ...
                %     "Options",["Save","Don't save","Cancel"], ...
                %     "DefaultOption",1,"CancelOption",3);
                % switch selection
                %     case 'Save'
%                         [app.project_id, app.session_id] = save_to_base(app.sourceapp, app.project_id, app.base.project.session, app.session_id, app.base.project_name, settings);
%                         Quantification_Module(app.sourceapp, app.base.project_name, app.project_id, app.session_id)
%                         delete(app)
            %         case "Don't save"
            %             Quantification_Module(app.sourceapp, app.base.project_name, app.project_id, app.session_id)
            %             delete(app)
            %         case 'Cancel'
            %             return
            %     end
            % 
            % catch ME
            %     return
            % end      
            % 
        end

        % Menu selected function: SaveSessiontoBaseandExitMenu
        function SaveSessiontoBaseandExitMenuSelected(app, event)

            try

                app.base.project.session.config.module_status.drift = true;

                % uiconfirm ask user if finished
                fig = app.UIFigure;
                msg = "Do you want to save the data?";
                title = "ICP-Base";
                selection = uiconfirm(fig,msg,title, ...
                    "Options",["Save","Don't save","Cancel"], ...
                    "DefaultOption",1,"CancelOption",3);
                switch selection
                    case 'Save'
                        [app.project_id, app.session_id] = save_to_base(app.sourceapp, app.project_id, app.base.project.session, app.session_id, app.base.project_name, settings);
                        delete(app)
                    case "Don't save"
                        delete(app)
                    case 'Cancel'
                        return
                end

            catch ME
                return
            end      
            
        end

        % Value changed function: BracketidDropDown
        function BracketidDropDownValueChanged(app, event)
            app.selected_bracket_id = app.BracketidDropDown.Value;

            update_downhole_module(app)
        end

        % Button pushed function: ClassificationButton, ImportButton, 
        % IntervalButton, QuantificationButton, ResultsButton
        function ImportButtonPushed(app, event)
            try

                % try
                %     % uiconfirm ask user if finished
                %     fig = app.UIFigure;
                %     msg = "Do you want to save the data?";
                %     title = "DIQES";
                %     selection = uiconfirm(fig,msg,title, ...
                %         "Options",["Save","Don't save","Cancel"], ...
                %         "DefaultOption",1,"CancelOption",3);
                %
                %     switch selection
                %         case 'Save'

                [app.project_id, app.session_id] = save_to_base(app.sourceapp, app.project_id, app.base.project.session, app.session_id, app.base.project_name, settings);

                switch event.Source.Parent.Parent.Parent.Parent.CallbackObject.Text
                    case 'Import'
                        Import_Module(app.sourceapp, app.base.project_name, app.project_id, app.session_id)
                    case 'Interval'
                        Interval_Module(app.sourceapp, app.base.project_name, app.project_id, app.session_id)
                    case 'Classification'
                        Classification_Module(app.sourceapp, app.base.project_name, app.project_id, app.session_id)
                    case 'Drift'
                        Drift_Module(app.sourceapp, app.base.project_name, app.project_id, app.session_id)
                    case 'Quantification'
                        Quantification_Module(app.sourceapp, app.base.project_name, app.project_id, app.session_id)
                    case 'Results'
                        Results_Module(app.sourceapp, app.base.project_name, app.project_id, app.session_id)
                end

                delete(app)

                %         case "Don't save"
                %
                %             switch event.Source.Parent.Parent.Parent.Parent.CallbackObject.Text
                %                 case 'Import'
                %                     Import_Module(app.sourceapp, app.base.project_name, app.project_id, app.session_id)
                %                 case 'Interval'
                %                     Interval_Module(app.sourceapp, app.base.project_name, app.project_id, app.session_id)
                %                 case 'Classification'
                %                     Classification_Module(app.sourceapp, app.base.project_name, app.project_id, app.session_id)
                %                 case 'Drift'
                %                     Drift_Module(app.sourceapp, app.base.project_name, app.project_id, app.session_id)
                %                 case 'Quantification'
                %                     Quantification_Module(app.sourceapp, app.base.project_name, app.project_id, app.session_id)
                %                 case 'Results'
                %                     Results_Module(app.sourceapp, app.base.project_name, app.project_id, app.session_id)
                %             end
                %
                %             delete(app)
                %
                %         case 'Cancel'
                %
                %             return
                %     end
                %
                % catch ME
                %     return
                % end

            catch ME
                DisplayError(ME,'Proceed to other module failed')
                message = "Proceeding to other module failed";
                uialert(app.UIFigure,message,"Warning", ...
                    "Icon","warning");

                return
            end
        end

        % Value changed function: RatioDropDown
        function RatioDropDownValueChanged(app, event)
            %value = app.RatioDropDown.Value;
            update_downhole_module(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.Icon = 'icon_48.png';
            app.BaseMenu = uimenu(app.UIFigure);
            app.BaseMenu.Text = 'Base';
            app.SaveSessiontoBaseandExitMenu = uimenu(app.BaseMenu);
            app.SaveSessiontoBaseandExitMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveSessiontoBaseandExitMenuSelected, true);
            app.SaveSessiontoBaseandExitMenu.Text = 'Save Session to Base and Exit';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.ResidualAxes = uiaxes(app.GridLayout);
            zlabel(app.ResidualAxes, 'Z')
            app.RatioAxes = uiaxes(app.GridLayout);
            xlabel(app.RatioAxes, 'Sweeps')
            ylabel(app.RatioAxes, 'Ratio')
            zlabel(app.RatioAxes, 'Z')
            app.RatioAxes.YScale = 'log';
            app.ProceedtoQuantificationModuleButton = uibutton(app.GridLayout, 'push');
            app.ProceedtoQuantificationModuleButton.ButtonPushedFcn = createCallbackFcn(app, @ProceedtoQuantificationModuleButtonPushed2, true);
            app.ProceedtoQuantificationModuleButton.Text = 'Proceed to Quantification Module';
            app.ProgressPanel = uipanel(app.GridLayout);
            app.IntervalButton = uibutton(app.ProgressPanel, 'push');
            app.IntervalButton.ButtonPushedFcn = createCallbackFcn(app, @ImportButtonPushed, true);
            app.IntervalButton.Text = 'Interval';
            app.ImportButton = uibutton(app.ProgressPanel, 'push');
            app.ImportButton.ButtonPushedFcn = createCallbackFcn(app, @ImportButtonPushed, true);
            app.ImportButton.Text = 'Import';
            app.ClassificationButton = uibutton(app.ProgressPanel, 'push');
            app.ClassificationButton.ButtonPushedFcn = createCallbackFcn(app, @ImportButtonPushed, true);
            app.ClassificationButton.Text = 'Classification';
            app.DriftButton = uibutton(app.ProgressPanel, 'push');
            app.DriftButton.Text = 'Drift';
            app.QuantificationButton = uibutton(app.ProgressPanel, 'push');
            app.QuantificationButton.ButtonPushedFcn = createCallbackFcn(app, @ImportButtonPushed, true);
            app.QuantificationButton.Text = 'Quantification';
            app.ResultsButton = uibutton(app.ProgressPanel, 'push');
            app.ResultsButton.ButtonPushedFcn = createCallbackFcn(app, @ImportButtonPushed, true);
            app.ResultsButton.Text = 'Results';
            app.CalibratorDropDownLabel = uilabel(app.GridLayout);
            app.CalibratorDropDownLabel.Text = 'Calibrator';
            app.CalibratorDropDown = uidropdown(app.GridLayout);
            app.CalibratorDropDown.Items = {};
            app.CalibratorDropDown.ValueChangedFcn = createCallbackFcn(app, @CalibratorDropDownValueChanged, true);
            app.CalibratorDropDown.Value = {};
            app.BracketidDropDownLabel = uilabel(app.GridLayout);
            app.BracketidDropDownLabel.Text = 'Bracket id';
            app.BracketidDropDown = uidropdown(app.GridLayout);
            app.BracketidDropDown.Items = {};
            app.BracketidDropDown.ValueChangedFcn = createCallbackFcn(app, @BracketidDropDownValueChanged, true);
            app.BracketidDropDown.Value = {};
            app.RatioDropDownLabel = uilabel(app.GridLayout);
            app.RatioDropDownLabel.Text = 'Ratio:';
            app.RatioDropDown = uidropdown(app.GridLayout);
            app.RatioDropDown.Items = {};
            app.RatioDropDown.ValueChangedFcn = createCallbackFcn(app, @RatioDropDownValueChanged, true);
            app.RatioDropDown.Value = {};
            app.FitDropDownLabel = uilabel(app.GridLayout);
            app.FitDropDownLabel.Text = 'Fit:';
            app.FitDropDown = uidropdown(app.GridLayout);
            app.FitDropDown.Items = {'Linear'};
            app.FitDropDown.Value = 'Linear';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Downhole_Correction_Module(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end