classdef Monitoring_Module < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MonitoringModuleUIFigure        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        GasBackgroundTab                matlab.ui.container.Tab
        LogscaleCheckBox                matlab.ui.control.CheckBox
        LegendCheckBox                  matlab.ui.control.CheckBox
        BGElementListBox                matlab.ui.control.ListBox
        BackgroundAxes                  matlab.ui.control.UIAxes
        FluenceTab                      matlab.ui.container.Tab
        RepetitionrateDropDown          matlab.ui.control.DropDown
        RepetitionrateDropDownLabel     matlab.ui.control.Label
        dev2Button                      matlab.ui.control.Button
        SetFluenceDropDown              matlab.ui.control.DropDown
        SetFluenceDropDownLabel         matlab.ui.control.Label
        CarriergasflowDropDown          matlab.ui.control.DropDown
        CarriergasflowDropDownLabel     matlab.ui.control.Label
        SpotsizeDropDown                matlab.ui.control.DropDown
        SpotsizeDropDownLabel           matlab.ui.control.Label
        ReferenceMaterialDropDown       matlab.ui.control.DropDown
        ReferenceMaterialDropDownLabel  matlab.ui.control.Label
        FluenceElementListBox           matlab.ui.control.ListBox
        FluenceAxes                     matlab.ui.control.UIAxes
    end

    
    properties (Access = public)

        base

    end
    
    properties (Access = private)

        bg_table

    end


    methods (Access = private)
        
        % Custom function to center the figure
        function center_figure(app, hfig)
            try
                screenSize = get(0,'ScreenSize');
                figPos = hfig.Position;
                figWidth = figPos(3);
                figHeight = figPos(4);
                newX = (screenSize(3) - figWidth) / 2;
                newY = (screenSize(4) - figHeight) / 2;
                hfig.Position=[newX, newY, figWidth, figHeight];
                figure(hfig)
            catch
                ME
                disp('CenterFig_fun failed!')
                disp(ME.message)
            end
        end

        %%%%%%% Background Monitoring %%%%%%%
        function update_background_tab(app)

            compile_background_table(app)

            % populate the elementlistbox
            app.BGElementListBox.Items = ['All'; app.bg_table.Properties.VariableNames(4:end)'];
            app.BGElementListBox.ItemsData = 0:numel(app.BGElementListBox.Items)-1;

            if isempty(app.BGElementListBox.Value)
                app.BGElementListBox.Value = 0;
            end

            plot_background_table(app)

        end

        function compile_background_table(app)
            % COMPILE_BACKGROUND_TABLE Fast compilation of average background counts per second.
            %
            % Returns a table where each row is a session with:
            % - project_name
            % - session_name
            % - date
            % - average background cps for each element (NaN if element not present)

            % --- Step 1: Pre-allocate and single pass data extraction ---

            % Initialize cell arrays
            meta_data = {};
            session_bg_data = {};
            all_element_names = {};

            % Single pass through all data
            for proj_idx = 1:length(app.base)
                if ~isfield(app.base(proj_idx), 'project')
                    continue;
                end

                project_name = app.base(proj_idx).project_name;

                for sess_idx = 1:length(app.base(proj_idx).project)
                    session = app.base(proj_idx).project(sess_idx).session;

                    % Skip sessions without the necessary data or wrong datatype
                    if ~isfield(session, 'config') || ~isfield(session, 'spot') || ...
                            ~isfield(session.config, 'datatype') || ~strcmp(session.config.datatype, 'Continuous')
                        continue;
                    end

                    % Store metadata
                    if isfield(session, 'meta') && isfield(session.meta, 'date')
                        session_date = session.meta.date;
                    else
                        session_date = '';
                    end
                    meta_data = [meta_data; {project_name, session.session_name, session_date}]; %#ok<AGROW>

                    % --- Vectorized background calculation ---
                    signal_data = session.spot.signal_data;

                    starts_cell = session.spot.background_starts;
                    ends_cell = session.spot.background_ends;

                    valid_indices_mask = ~cellfun('isempty', starts_cell) & ~cellfun('isempty', ends_cell);

                    if ~any(valid_indices_mask)
                        % No valid background intervals, skip this session's calculation
                        session_bg_data = [session_bg_data; {struct()}]; %#ok<AGROW>
                        continue;
                    end

                    % Convert all numeric types to double to ensure consistency
                    bg_starts = cellfun(@double, starts_cell(valid_indices_mask));
                    bg_ends = cellfun(@double, ends_cell(valid_indices_mask));

                    % Create a logical index vector for all background points
                    bg_indices = false(height(signal_data), 1);
                    for i = 1:length(bg_starts)
                        bg_indices(bg_starts(i):bg_ends(i)) = true;
                    end

                    session_elements = signal_data.Properties.VariableNames(2:end);

                    % Calculate mean for all elements in one operation
                    avg_bgs = mean(signal_data{bg_indices, session_elements}, 1, 'omitnan');

                    % Store results as a struct for this session
                    temp_struct = struct();
                    for i = 1:length(session_elements)
                        temp_struct.(session_elements{i}) = avg_bgs(i);
                    end
                    session_bg_data = [session_bg_data; {temp_struct}]; %#ok<AGROW>

                    % Collect all unique element names
                    all_element_names = [all_element_names, session_elements]; %#ok<AGROW>
                end
            end

            % --- Step 2: Consolidate data into the final table ---
            session_count = size(meta_data, 1);
            if session_count == 0
                app.bg_table = table(); % Create an empty table if no sessions were processed
                return;
            end

            % Get final unique list of all elements found
            unique_elements = unique(all_element_names, 'stable');
            num_elements = length(unique_elements);

            % Create a map for fast element name to column index lookup
            elem_map = containers.Map(unique_elements, 1:num_elements);

            % Pre-allocate a NaN matrix for all element data
            element_matrix = NaN(session_count, num_elements);

            % Populate the matrix efficiently
            for i = 1:session_count
                sess_data = session_bg_data{i};
                sess_fields = fieldnames(sess_data);
                for j = 1:length(sess_fields)
                    fn = sess_fields{j};
                    if isKey(elem_map, fn) % Ensure element is in the map
                        col_idx = elem_map(fn);
                        element_matrix(i, col_idx) = sess_data.(fn);
                    end
                end
            end

            % Create the final table
            app.bg_table = array2table(meta_data, 'VariableNames', {'project_name', 'session_name', 'date'});
            element_table = array2table(element_matrix, 'VariableNames', unique_elements);

            app.bg_table = [app.bg_table, element_table];
        end

        function plot_background_table(app)

            % PLOT_BACKGROUND_TABLE Plots the compiled background data.
            %
            % The plot is generated on app.BackgroundAxes with dates on the
            % x-axis and average background cps on the y-axis for each element.

            ax = app.BackgroundAxes;
            cla(ax, 'reset'); % Clear and reset the axes

            % Check if the background table is populated
            if isempty(app.bg_table) || height(app.bg_table) == 0
                title(ax, 'No Background Data to Display');
                xlabel(ax, '');
                ylabel(ax, '');
                legend(ax, 'off');
                return;
            end

            % --- Data Preparation ---

            % Make a local copy to filter and sort
            plot_data = app.bg_table;

            % Remove rows where the date is empty.
            % This handles cases where the date is an empty cell or contains an empty char array.
            if iscell(plot_data.date)
                is_empty_date = cellfun(@isempty, plot_data.date);
                plot_data = plot_data(~is_empty_date, :);
            end

            % Check if there is any data left to plot
            if isempty(plot_data) || height(plot_data) == 0
                title(ax, 'No Background Data with Valid Dates to Display');
                xlabel(ax, '');
                ylabel(ax, '');
                legend(ax, 'off');
                return;
            end

            % Sort the table by date chronologically
            plot_data = sortrows(plot_data, 'date');

            % Extract data for plotting
            dates = plot_data.date;
            if isequal(app.BGElementListBox.Value, 0) % All is chosen
                element_names = plot_data.Properties.VariableNames(4:end);
            else
                all_names = plot_data.Properties.VariableNames(4:end)';
                element_names = all_names(app.BGElementListBox.Value);
            end

            element_data = plot_data{:, element_names};

            % --- Plotting ---

            % Plot all elements against a categorical index
            plot(ax, 1:height(plot_data), element_data, '.-', 'MarkerSize', 15);

            % --- Axes Formatting ---

            % Set x-axis ticks and labels
            xticks(ax, 1:height(plot_data));

            xticklabels(ax, dates); % Fallback for non-datetime values

            xtickangle(ax, 45);
            xlim(ax, [0.5, height(plot_data) + 0.5]);

            % Set labels and title
            title(ax, 'Average Background Signal Over Time');
            xlabel(ax, 'Session Date');
            ylabel(ax, 'Background (cps)');
            if app.LogscaleCheckBox.Value
                ax.YScale = 'log';
            else
                ax.YScale = 'linear';
            end

            % Add a grid and legend
            grid(ax, 'on');
            if app.LegendCheckBox.Value
                legend(ax, element_names, 'Interpreter', 'none');
            end

        end


        %%%%%%% Fluence Calibration Monitoring %%%%%%%

        function update_fluence_tab(app)

            % populate the elementlistbox
%             app.ReferenceMaterialDropDown.Items = xy;
%             app.ReferenceMaterialDropDown.ItemsData = app.ReferenceMaterialDropDown.Items;
% 

        end

        function compile_fluence_table(app)

            keyboard
            for proj_idx = 1:length(app.base)
                if ~isfield(app.base(proj_idx), 'project')
                    continue;
                end

                project_name = app.base(proj_idx).project_name;

                for sess_idx = 1:length(app.base(proj_idx).project)
                    session = app.base(proj_idx).project(sess_idx).session;

                    % Skip sessions without the necessary data or wrong datatype
                    if ~isfield(session, 'config') || ~isfield(session, 'spot') || ...
                            ~isfield(session.config, 'datatype') || ~strcmp(session.config.datatype, 'Continuous')
                        continue;
                    end

                    % Store metadata
                    if isfield(session.config, 'date')
                        dates = session.config.date;
                    else
                        dates = '';
                    end

                    cps_data = session.spot.bkgd_corrected_cps;

                    % now I have to filter according to the dropdown menus
                    % and only extract the data that is really wanted.

                    standard_name = session.spot.referencematerial;

                    spotsize = session.meta.laser_parameters.spotsize;
                    reprate = session.meta.laser_parameters.rep_rates;
                    fluence = session.meta.laser_parameters.fluences;                    
                    he_flow = session.meta.laser_parameters.he_flow;    


                end
            end


        end


    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, sourceapp)
             center_figure(app, app.MonitoringModuleUIFigure)

            if exist("sourceapp","var")
                %app.sourceapp = sourceapp;
                app.base = sourceapp.base;
                
            end

            update_background_tab(app)


        end

        % Callback function
        function devButtonPushed(app, event)
            
           compile_background_table(app)
            
           update_background_tab(app)
         

        end

        % Value changed function: BGElementListBox
        function BGElementListBoxValueChanged(app, event)

           plot_background_table(app)
         

        end

        % Value changed function: FluenceElementListBox
        function FluenceElementListBoxValueChanged(app, event)
            value = app.FluenceElementListBox.Value;
            
        end

        % Value changed function: ReferenceMaterialDropDown
        function ReferenceMaterialDropDownValueChanged(app, event)
            value = app.ReferenceMaterialDropDown.Value;
            
        end

        % Value changed function: SpotsizeDropDown
        function SpotsizeDropDownValueChanged(app, event)
            value = app.SpotsizeDropDown.Value;
            
        end

        % Value changed function: CarriergasflowDropDown
        function CarriergasflowDropDownValueChanged(app, event)
            value = app.CarriergasflowDropDown.Value;
            
        end

        % Value changed function: SetFluenceDropDown
        function SetFluenceDropDownValueChanged(app, event)
            value = app.SetFluenceDropDown.Value;
            
        end

        % Button pushed function: dev2Button
        function dev2ButtonPushed(app, event)
            compile_fluence_table(app)
        end

        % Value changed function: RepetitionrateDropDown
        function RepetitionrateDropDownValueChanged(app, event)
            value = app.RepetitionrateDropDown.Value;
            
        end

        % Value changed function: LegendCheckBox
        function LegendCheckBoxValueChanged(app, event)

           plot_background_table(app)
        end

        % Value changed function: LogscaleCheckBox
        function LogscaleCheckBoxValueChanged(app, event)

             plot_background_table(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MonitoringModuleUIFigure = uifigure('Visible', 'off');
            app.MonitoringModuleUIFigure.Name = 'Monitoring Module';
            app.MonitoringModuleUIFigure.Icon = 'icon_48.png';
            app.TabGroup = uitabgroup(app.MonitoringModuleUIFigure);
            app.GasBackgroundTab = uitab(app.TabGroup);
            app.GasBackgroundTab.Title = 'Gas Background';
            app.BackgroundAxes = uiaxes(app.GasBackgroundTab);
            app.BGElementListBox = uilistbox(app.GasBackgroundTab);
            app.BGElementListBox.Items = {};
            app.BGElementListBox.Multiselect = 'on';
            app.BGElementListBox.ValueChangedFcn = createCallbackFcn(app, @BGElementListBoxValueChanged, true);
            app.BGElementListBox.Value = {};
            app.LegendCheckBox = uicheckbox(app.GasBackgroundTab);
            app.LegendCheckBox.ValueChangedFcn = createCallbackFcn(app, @LegendCheckBoxValueChanged, true);
            app.LegendCheckBox.Text = 'Legend';
            app.LogscaleCheckBox = uicheckbox(app.GasBackgroundTab);
            app.LogscaleCheckBox.ValueChangedFcn = createCallbackFcn(app, @LogscaleCheckBoxValueChanged, true);
            app.LogscaleCheckBox.Text = 'Log-scale';
            app.LogscaleCheckBox.Value = true;
            app.FluenceTab = uitab(app.TabGroup);
            app.FluenceTab.Title = 'Fluence';
            app.FluenceAxes = uiaxes(app.FluenceTab);
            app.FluenceElementListBox = uilistbox(app.FluenceTab);
            app.FluenceElementListBox.Items = {};
            app.FluenceElementListBox.Multiselect = 'on';
            app.FluenceElementListBox.ValueChangedFcn = createCallbackFcn(app, @FluenceElementListBoxValueChanged, true);
            app.FluenceElementListBox.Value = {};
            app.ReferenceMaterialDropDownLabel = uilabel(app.FluenceTab);
            app.ReferenceMaterialDropDownLabel.Text = 'Reference Material';
            app.ReferenceMaterialDropDown = uidropdown(app.FluenceTab);
            app.ReferenceMaterialDropDown.Items = {};
            app.ReferenceMaterialDropDown.ValueChangedFcn = createCallbackFcn(app, @ReferenceMaterialDropDownValueChanged, true);
            app.ReferenceMaterialDropDown.Value = {};
            app.SpotsizeDropDownLabel = uilabel(app.FluenceTab);
            app.SpotsizeDropDownLabel.Text = 'Spot size';
            app.SpotsizeDropDown = uidropdown(app.FluenceTab);
            app.SpotsizeDropDown.Items = {};
            app.SpotsizeDropDown.ValueChangedFcn = createCallbackFcn(app, @SpotsizeDropDownValueChanged, true);
            app.SpotsizeDropDown.Value = {};
            app.CarriergasflowDropDownLabel = uilabel(app.FluenceTab);
            app.CarriergasflowDropDownLabel.Text = 'Carrier gas flow';
            app.CarriergasflowDropDown = uidropdown(app.FluenceTab);
            app.CarriergasflowDropDown.Items = {};
            app.CarriergasflowDropDown.ValueChangedFcn = createCallbackFcn(app, @CarriergasflowDropDownValueChanged, true);
            app.CarriergasflowDropDown.Value = {};
            app.SetFluenceDropDownLabel = uilabel(app.FluenceTab);
            app.SetFluenceDropDownLabel.Text = 'Set Fluence';
            app.SetFluenceDropDown = uidropdown(app.FluenceTab);
            app.SetFluenceDropDown.Items = {};
            app.SetFluenceDropDown.ValueChangedFcn = createCallbackFcn(app, @SetFluenceDropDownValueChanged, true);
            app.SetFluenceDropDown.Value = {};
            app.dev2Button = uibutton(app.FluenceTab, 'push');
            app.dev2Button.ButtonPushedFcn = createCallbackFcn(app, @dev2ButtonPushed, true);
            app.dev2Button.Text = 'dev2';
            app.RepetitionrateDropDownLabel = uilabel(app.FluenceTab);
            app.RepetitionrateDropDownLabel.Text = 'Repetition rate';
            app.RepetitionrateDropDown = uidropdown(app.FluenceTab);
            app.RepetitionrateDropDown.Items = {};
            app.RepetitionrateDropDown.ValueChangedFcn = createCallbackFcn(app, @RepetitionrateDropDownValueChanged, true);
            app.RepetitionrateDropDown.Value = {};
            % Show the figure after all components are created
            app.MonitoringModuleUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Monitoring_Module(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MonitoringModuleUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MonitoringModuleUIFigure)
        end
    end
end