classdef Spike_Submodule < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure      matlab.ui.Figure
        FinishButton  matlab.ui.control.Button
        AcceptButton  matlab.ui.control.Button
        IgnoreButton  matlab.ui.control.Button
        SpikeAxes     matlab.ui.control.UIAxes
    end


    properties (Access = public)
        source

        eliminated_spikes   % Table to store detected spike information
        corrected_data      % Data with corrected spike values
        current_spike_index % Index of the currently viewed spike
        spot
        spot_indices
        current_spot_to_plot

        data_to_correct

        inclusion
    end


    methods (Access = private)

        function center_figure(app, hfig)
            try
                screenSize = get(0,'ScreenSize');
                figPos = hfig.Position;
                figWidth = figPos(3);
                figHeight = figPos(4);
                newX = (screenSize(3) - figWidth) / 2;
                newY = (screenSize(4) - figHeight) / 2;
                hfig.Position=[newX, newY, figWidth, figHeight];
                figure(hfig)
            catch
                ME
                disp('CenterFig_fun failed!')
                disp(ME.message)
            end
        end

        function find_and_correct_spikes(app)

            if app.inclusion == false
                % consider all data
                app.data_to_correct = app.current_spot_to_plot(:, 2:end);

            elseif app.inclusion == true
                % consider only inclusion data

                inc_start = app.source.base.project.session.spot.inclusion_1_starts{app.spot};
                inc_end = app.source.base.project.session.spot.inclusion_1_ends{app.spot};

                if strcmp(app.source.base.project.session.config.datatype, 'Continuous')

                    app.data_to_correct =  app.source.base.project.session.spot.signal_data(inc_start:inc_end, 2:end);

                elseif strcmp(app.source.base.project.session.config.datatype, 'Single')

                    app.data_to_correct = app.current_spot_to_plot(inc_start:inc_end , 2:end);

                end

            end

            % Define Constants as single precision
            cpvalueseven = single(2.097); % For N=7 measurements, significance level 1%
            cpvaluenine = single(2.323);  % For N=9 measurements, significance level 1%
            threshold = single(600);     % Minimum threshold for spike detection [cps]

            % Initialize output and convert data to single for performance
            corrected_data_matrix = single(table2array(app.data_to_correct));
            spikes = table('Size', [0, 3], ...
                'VariableTypes', {'single', 'single', 'single'}, ...
                'VariableNames', {'RowIndex', 'ColumnIndex', 'OriginalValue'});
            % Apply Grubbs' test correction using a moving window
            for col = 1:size(corrected_data_matrix, 2)
                % Start from tstep=5 to accommodate a 9-point window (t-4 to t+4)
                for tstep = 5:(size(corrected_data_matrix, 1) - 4)
                    csp = corrected_data_matrix(tstep, col);
                    % Only check points above the minimum threshold
                    if csp > threshold
                        % Define the 9-point window around the current point
                        window9 = corrected_data_matrix(tstep-4:tstep+4, col);

                        % The 7-point window is a subset of the 9-point window
                        window7 = window9([1:4, 6:9]); % Excludes point at relative index 5 (csp)

                        % Calculate statistics for both windows
                        meanseven = mean(window7);
                        stdseven = std(window7);
                        meannine = mean(window9);
                        stdnine = std(window9);

                        % Check if the point is an outlier using both criteria
                        if stdseven > 0 && stdnine > 0 && ...
                                ((csp - meanseven) / stdseven) > cpvalueseven && ...
                                ((csp - meannine) / stdnine) > cpvaluenine

                             % Replace with the mean of the 6 surrounding points (3 before, 3 after)
                             surrounding = corrected_data_matrix(tstep-3:tstep+3, col);
                            surrounding(4) = []; % Exclude the spike itself
                            corrected_value = mean(surrounding);
                            % Store spike info before correction
                            new_spike = {tstep, col, csp};
                            spikes = [spikes; new_spike]; %#ok<AGROW>

                            % Apply correction to the matrix
                            corrected_data_matrix(tstep, col) = corrected_value;


                        end
                    end
                end
            end

            app.eliminated_spikes = spikes;
            app.corrected_data = array2table(corrected_data_matrix, 'VariableNames', app.data_to_correct.Properties.VariableNames());

            if ~isempty(spikes)
                app.current_spike_index = 1;
            else
                app.current_spike_index = 0;
            end
        end


        function update_spike_plot(app)

            cla(app.SpikeAxes, 'reset');

            if app.current_spike_index == 0
                app.SpikeAxes.Title.String = 'No spikes detected.';

                message = "No spikes detected";
                uialert(app.UIFigure,message,"Warning", ...
                    "Icon","warning");

                return
            end

            uncorrected_data = app.data_to_correct;

            current_spike = app.eliminated_spikes(app.current_spike_index, :);
            row = current_spike.RowIndex;
            col = current_spike.ColumnIndex;
            original_value = current_spike.OriginalValue;
            corrected_value = app.corrected_data{row, col};
            element_name = uncorrected_data.Properties.VariableNames{col};
            
            % Plot original and corrected data for the affected trace
            hold(app.SpikeAxes, 'on');
            plot(app.SpikeAxes, table2array(uncorrected_data(:, col)), 'r.-', 'DisplayName', 'Spike','HandleVisibility','off');
            plot(app.SpikeAxes, table2array(app.corrected_data(:, col)), 'k.-', 'DisplayName', element_name);
            
            % Highlight the spike and the corrected value
            plot(app.SpikeAxes, row, original_value, 'rx', 'MarkerSize', 10, 'LineWidth', 2, 'DisplayName', 'Spike');
            plot(app.SpikeAxes, row, corrected_value, 'gx', 'MarkerSize', 12, 'LineWidth', 2, 'DisplayName', 'Corrected');
            hold(app.SpikeAxes, 'off');
            
            % Configure plot
            title_str = sprintf('Element: %s - Spike %d of %d (Row: %d)', ...
                element_name, app.current_spike_index, height(app.eliminated_spikes), row);
            app.SpikeAxes.Title.String = title_str;
            app.SpikeAxes.Title.Interpreter = 'none'; % To correctly display names with underscores
            legend(app.SpikeAxes, 'Location', 'southeast', 'Interpreter', 'none');
            grid(app.SpikeAxes, 'on');
            
            % Zoom in on the spike
            zoom_window = 30; % Number of points to show on each side
            total_points = height(uncorrected_data);
            x_min = max(1, row - zoom_window);
            x_max = min(total_points, row + zoom_window);
            xlim(app.SpikeAxes, [x_min, x_max]);

            % Set Y-limits with padding
            visible_data = uncorrected_data{x_min:x_max, col};
            y_min_val = min(visible_data(visible_data>0)); % Min for log scale
            y_max_val = max(visible_data);
            y_padding = (y_max_val - y_min_val) * 0.1;
            if y_padding == 0; y_padding = y_max_val * 0.1; end
            ylim(app.SpikeAxes, [max(0.1, y_min_val - y_padding), y_max_val + y_padding]);

            set(app.SpikeAxes, 'YScale', 'log');
     

          % Update button states
            app.AcceptButton.Enable = 'on';
            app.IgnoreButton.Enable = 'on';

        end


    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, int_mod, inclusion)

            center_figure(app, app.UIFigure)

            app.source = int_mod;
            app.spot = app.source.selected_spot;
            app.spot_indices = app.source.active_spot_indices;
            app.current_spot_to_plot = app.source.active_spot;

            app.inclusion = inclusion;


            if app.inclusion == true
                % consider only inclusion data

                if isempty(app.source.base.project.session.spot.inclusion_1_starts{app.spot}) || isempty(app.source.base.project.session.spot.inclusion_1_ends{app.spot})

                    message = "Set inclusion interval first";
                    uialert(app.UIFigure,message,"Warning", ...
                        "Icon","warning");
                    return

                end
            end


            find_and_correct_spikes(app);
            update_spike_plot(app);
        end

        % Button pushed function: AcceptButton
        function AcceptButtonPushed(app, event)
            if app.current_spike_index > 0

                if app.current_spike_index < height(app.eliminated_spikes)
                    app.current_spike_index = app.current_spike_index + 1;
                    update_spike_plot(app);
                end

            end
        end

        % Button pushed function: IgnoreButton
        function IgnoreButtonPushed(app, event)
            if app.current_spike_index > 0

                current_spike = app.eliminated_spikes(app.current_spike_index, :);

                app.corrected_data{current_spike.RowIndex, current_spike.ColumnIndex} = current_spike.OriginalValue;

                % Remove the spike from the tracking tables
                app.eliminated_spikes(app.current_spike_index, :) = [];

                if app.current_spike_index < height(app.eliminated_spikes)
                    app.current_spike_index = app.current_spike_index + 1;
                    update_spike_plot(app);
                end
            end
        end

        % Callback function
        function devButtonPushed(app, event)

            keyboard
        end

        % Button pushed function: FinishButton
        function FinishButtonPushed(app, event)

            %Ensure the storage for eliminated spikes exists
            if ~isfield(app.source.base.project.session.spot, 'eliminated_spikes')
                eliminated_spikes_cell = cell(size(app.source.base.project.session.spot.name));
                app.source.base.project.session.spot.eliminated_spikes = cellfun(@single, eliminated_spikes_cell, 'UniformOutput', false);
            end

            if app.inclusion == false
                if strcmp(app.source.base.project.session.config.datatype, 'Continuous')
                    % Store results
                    app.source.base.project.session.spot.signal_data(app.spot_indices, 2:end) = app.corrected_data;
                    app.source.base.project.session.spot.eliminated_spikes{app.spot} = [app.source.base.project.session.spot.eliminated_spikes{app.spot}; app.eliminated_spikes];

                elseif strcmp(app.source.base.project.session.config.datatype, 'Single')
                    % Store results
                    app.source.base.project.session.spot.signal_data{app.spot}(:,2:end) = app.corrected_data;
                    app.source.base.project.session.spot.eliminated_spikes{app.spot} = [app.source.base.project.session.spot.eliminated_spikes{app.spot}; app.eliminated_spikes];
                end

            elseif app.inclusion == true
                inc_start = app.source.base.project.session.spot.inclusion_1_starts{app.spot};
                inc_end = app.source.base.project.session.spot.inclusion_1_ends{app.spot};

                if strcmp(app.source.base.project.session.config.datatype, 'Continuous')

                    % Store results
                    app.source.base.project.session.spot.signal_data(inc_start:inc_end, 2:end)  = app.corrected_data;

                    offset = inc_start - min(app.spot_indices);
                    app.eliminated_spikes.RowIndex = app.eliminated_spikes.RowIndex + offset;
                    app.source.base.project.session.spot.eliminated_spikes{app.spot} = [app.source.base.project.session.spot.eliminated_spikes{app.spot}; app.eliminated_spikes];

                elseif strcmp(app.source.base.project.session.config.datatype, 'Single')

                    % Store results
                    app.source.base.project.session.spot.signal_data{app.spot}(inc_start:inc_end ,2:end) = app.corrected_data;

                    offset = inc_start - min(app.spot_indices);
                    app.eliminated_spikes.RowIndex = app.eliminated_spikes.RowIndex + offset;
                    % this is fucked because there is an offset
                    app.source.base.project.session.spot.eliminated_spikes{app.spot} = [app.source.base.project.session.spot.eliminated_spikes{app.spot}; app.eliminated_spikes];
                end

            end

            update_Interval_Module_GUI(app.source)
            delete(app)


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Icon = 'ICP-Base Icon.png';
            app.UIFigure.Tag = 'spike_submodule';
            app.SpikeAxes = uiaxes(app.UIFigure);
            title(app.SpikeAxes, 'Title')
            xlabel(app.SpikeAxes, 'Sweeps')
            ylabel(app.SpikeAxes, 'Intensity [cps]')
            zlabel(app.SpikeAxes, 'Z')
            app.IgnoreButton = uibutton(app.UIFigure, 'push');
            app.IgnoreButton.ButtonPushedFcn = createCallbackFcn(app, @IgnoreButtonPushed, true);
            app.IgnoreButton.Text = 'Ignore';
            app.AcceptButton = uibutton(app.UIFigure, 'push');
            app.AcceptButton.ButtonPushedFcn = createCallbackFcn(app, @AcceptButtonPushed, true);
            app.AcceptButton.Text = 'Accept';
            app.FinishButton = uibutton(app.UIFigure, 'push');
            app.FinishButton.ButtonPushedFcn = createCallbackFcn(app, @FinishButtonPushed, true);
            app.FinishButton.Text = 'Finish';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Spike_Submodule(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end