classdef Table_builder_Submodule < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        TableBuilderUIFigure        matlab.ui.Figure
        TabGroup                    matlab.ui.container.TabGroup
        DataselectionTab            matlab.ui.container.Tab
        ExcludeCalibratorsCheckBox  matlab.ui.control.CheckBox
        MajorsasoxideswtCheckBox    matlab.ui.control.CheckBox
        RemovemassesfromelementnameCheckBox  matlab.ui.control.CheckBox
        SimplePanel                 matlab.ui.container.Panel
        IfyouexportthetableorproceedtotheVisModulewithLabel  matlab.ui.control.Label
        AdvancedPanel               matlab.ui.container.Panel
        SpotMetaTree                matlab.ui.container.CheckBoxTree
        SelectionModeDropDown       matlab.ui.control.DropDown
        SelectionModeDropDownLabel  matlab.ui.control.Label
        ProceedtoVisModuleButton    matlab.ui.control.Button
        ExportcsvTableButton        matlab.ui.control.Button
        ProjectSessionTree          matlab.ui.container.CheckBoxTree
        ContextMenu                 matlab.ui.container.ContextMenu
        ExportasmatMenu             matlab.ui.container.Menu
    end


%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%                     %%%%%%%%%%%%%%%%%%%%%%%%%                        %
%                     %%%      ICP-Base     %%%                        %
%                     %%%%%%%%%%%%%%%%%%%%%%%%%                        %
%                                                                      %
%  Copyright (C) 2026 Sebastian Stumpf                                 %
%                                                                      %
%  This program is free software: you can redistribute it and/or       %
%  modify it under the terms of the GNU General Public License as      %
%  published by the Free Software Foundation, either version 3 of      %
%  the License, or any later version.                                  %
%                                                                      %
%  This program is distributed in the hope that it will be useful,     %
%  but WITHOUT ANY WARRANTY; without even the implied warranty of      %
%  MERCHANTABILITY or FITNESS FOR A PARTICULAR PURPOSE. See the        %
%  GNU General Public License for more details.                        %
%                                                                      %
%  You should have received a copy of the GNU General Public License   %
%  along with this program. If not, see <https://www.gnu.org/licenses/>%
%                                                                      %
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

    properties (Access = public)
        sourceapp
        base
    end


    methods (Access = private)

        %%%%% GUI functions %%%%%%

        function ResetTree(app,Tree2Update)
            if not(exist('Tree2Update','var'))
                Tree2Update=app.ProjectSessionTree;
            end

            %             if ~isempty(Tree2Update.SelectedNodes)
            %                 app.SelectedNodes_MainTree = Tree2Update.SelectedNodes.NodeData;
            %             end

            a = Tree2Update.Children;
            a.delete;
        end

        function update_project_session_tree(app)

            Tree2Update=app.ProjectSessionTree;

            ResetTree(app,app.ProjectSessionTree)

            base_struct = app.base;

            if ~isempty(base_struct)
                for i = 1:numel(base_struct)
                    % Top level: Project name
                    projectName = base_struct(i).project_name;
                    p0 = uitreenode(Tree2Update, 'Text', string(projectName)); %, 'NodeData', []

                    % Check if there are projects under this base
                    projects = base_struct(i).project;
                    if ~isempty(projects)
                        for j = 1:numel(projects)
                            % Check if 'session' field exists and is not empty
                            if isfield(projects(j), 'session') && ~isempty(projects(j).session)
                                sessions = projects(j).session;
                                for k = 1:numel(sessions)
                                    sessionName = sessions(k).session_name;
                                    p1 = uitreenode(p0, 'Text', string(sessionName), 'NodeData', [i, j]);
                                    % Add more levels here if needed
                                end
                            end
                        end
                    end
                end
            end


        end

        function update_spot_meta_tree(app)

            Tree2Update=app.SpotMetaTree;

            ResetTree(app, app.SpotMetaTree);
            Tree2Update = app.SpotMetaTree;

            % Get checked nodes from your project/session tree
            checkedNodes = app.ProjectSessionTree.CheckedNodes; % adjust name if needed

            if isempty(checkedNodes)
                return
            end

            %%%%%%%%%%%%%%%%%%% Metadata Node %%%%%%%%%%%%%%%%%%%%

            % Top-level nodes
            metaNode = uitreenode(Tree2Update, 'Text', "Metadata", 'NodeData', "Metadata");

            hasICP = false;
            hasLA = false;

            % Loop through checked sessions
            for n = 1:numel(checkedNodes)
                nodeData = checkedNodes(n).NodeData;
                if isempty(nodeData)
                    continue
                end
                if numel(nodeData) == 2 && nodeData(2) > 0
                    j = nodeData(1); k = nodeData(2);
                    session = app.base(j).project(k).session;
                    if isfield(session, 'meta')
                        if isfield(session.meta, 'icp_ms_parameters')
                            hasICP = true;
                        end
                        if isfield(session.meta, 'laser_parameters')
                            hasLA = true;
                        end
                    end
                end
            end

            if hasLA
                uitreenode(metaNode, 'Text', "LA parameters", 'NodeData', "LA parameters");
            end
            if hasICP
                uitreenode(metaNode, 'Text', "ICP-MS parameters", 'NodeData', "ICP-MS parameters");
            end

            %%%%%%%%%%%%%%%%%%% Quantification Node %%%%%%%%%%%%%%%%%%%%
            quantNode = uitreenode(Tree2Update, 'Text', "Quantification", 'NodeData', "Quantification");

            % Standard quantification children
            quantLabels = [...
                "Matrix/Host mass fractions", ...
                "Matrix/Host limits of detection" ...
                "Matrix/Host counts-per-sweep", ...
                "Sensitivities" ...
                ];
            for q = 1:numel(quantLabels)
                uitreenode(quantNode, 'Text', quantLabels(q), 'NodeData', quantLabels(q));
            end

            % --- Only add Inclusion nodes if any checked session is Inclusion ---
            addInclusion = false;

            for n = 1:numel(checkedNodes)

                nodeData = checkedNodes(n).NodeData; % [j, k] indices for project, session
                if isempty(nodeData)
                    continue
                end
                if numel(nodeData) == 2 && nodeData(2) > 0
                    j = nodeData(1); k = nodeData(2);
                    session = app.base(j).project(k).session;
                    if isfield(session, 'config') && isfield(session.config, 'reductiontype') ...
                            && strcmp(session.config.reductiontype, "Inclusion")
                        addInclusion = true;
                        break;
                    end
                end
            end

            if addInclusion
                inclusionLabels = [...
                    "Host-Inclusion-unmixing" ...
                    "Inclusion mass fractions", ...
                    "Inclusion limits of detection", ...
                    "Inclusion counts-per-sweep", ...
                    "Mix mass fractions", ...
                    "Mix counts-per-sweep", ...
                    ];
                for q = 1:numel(inclusionLabels)
                    uitreenode(quantNode, 'Text', inclusionLabels(q), 'NodeData', inclusionLabels(q));
                end
            end

        end


        %%%%% Table building functions %%%%%%

        function Base_Table = build_assigned_table_from_base(app)
            % --- Get checked nodes from SpotMetaTree ---
            checkedMetaNodes = app.SpotMetaTree.CheckedNodes;
            if isempty(checkedMetaNodes)
                Base_Table = table();
                return
            end
            checkedMetaLabels = string({checkedMetaNodes.NodeData}); % assumes NodeData is string or char

            % Respect current UI behavior, but apply mass removal session-by-session inside this function
            removeMassesPerSession = false;
            if isprop(app, 'RemovemassesfromelementnameCheckBox')
                removeMassesPerSession = logical(app.RemovemassesfromelementnameCheckBox.Value);
            end

            % Helper to check if a label is checked
            isChecked = @(label) any(checkedMetaLabels == label);

            % --- Always needed: Metadata ---
            allProjects = {};
            allSessions = {};
            allComments = {};
            allAnalysts = {};
            allProcessors = {};
            allSpotnames = {};
            alldates = {};
            dynamicMetaData = struct();

            allTypes = {};
            allRefMaterials = {};
            allIntTimePoints = {};
            allusedBracket_id = {};

            % Host-Inclusion-unmixing
            allUnmixingVia = {};
            allMatrixOnlyTracer = {};
            allMatrixFromSpot = {};

            % Sensitivities
            allSensitivitiesRows = {};
            % Matrix/Host mass fractions
            allmatrixcompRows = {};
            % Inclusion mass fractions
            allinclcompRows = {};
            % Matrix/Host limits of detection
            allmatrixlodRows = {};
            % Inclusion limits of detection
            allincllodRows = {};
            % Mix mass fractions
            allmixcompRows = {};

            % cps
            allbkgdCpsRows_mat = {};
            allbkgdCpsRows_incl = {};
            allbkgdCpsRows_mix = {};

            % LA parameters
            allLaserParamTables = {};
            % ICP-MS parameters
            allICPMSParamTables = {};

            % Get checked nodes from the project/session tree
            checkedNodes = app.ProjectSessionTree.CheckedNodes;
            checkedIndices = [];
            for n = 1:numel(checkedNodes)
                nodeData = checkedNodes(n).NodeData;
                if ~isempty(nodeData) && numel(nodeData) == 2 && nodeData(2) > 0
                    checkedIndices = [checkedIndices; nodeData];
                end
            end

            % --- Pre-scan selected sessions to find all dynamic metadata fields ---
            for i = 1:numel(app.base)
                for j = 1:numel(app.base(i).project)
                    if isempty(checkedIndices) || ~ismember([i, j], checkedIndices, 'rows')
                        continue
                    end
                    if isfield(app.base(i).project(j), 'session')
                        session = app.base(i).project(j).session;
                    else
                        continue
                    end

                    if ~isfield(session, 'spot') || isempty(session.spot)
                        continue
                    end
                    nSpots = numel(session.spot.name);

                    if isfield(session, 'meta') && isstruct(session.meta)
                        metaFields = fieldnames(session.meta);
                        for f = 1:numel(metaFields)
                            fieldName = metaFields{f};
                            if iscell(session.meta.(fieldName)) && size(session.meta.(fieldName), 1) == nSpots
                                if ~isfield(dynamicMetaData, fieldName)
                                    dynamicMetaData.(fieldName) = {};
                                end
                            end
                        end
                    end
                end
            end

            dynamicMetaFields = fieldnames(dynamicMetaData);
            missingAssignmentSessions = {};

            for i = 1:numel(app.base)
                projectName = app.base(i).project_name;

                for j = 1:numel(app.base(i).project)
                    if isempty(checkedIndices) || ~ismember([i, j], checkedIndices, 'rows')
                        continue
                    end

                    if isfield(app.base(i).project(j), 'session')
                        session = app.base(i).project(j).session;
                    else
                        continue
                    end

                    % Skip if any module status is 0
                    if isfield(session, 'config') && isfield(session.config, 'module_status')
                        if any(structfun(@(x) x == 0, session.config.module_status))
                            continue
                        end
                    end

                    % Session meta
                    if isfield(session, 'session_name') && ~isempty(session.session_name)
                        sessionName = char(session.session_name);
                    else
                        sessionName = '';
                    end

                    if isfield(session, 'meta') && isfield(session.meta, 'date') && ~isempty(session.meta.date)
                        sessiondate = session.meta.date;
                    else
                        sessiondate = '';
                    end

                    if isfield(session, 'meta') && isfield(session.meta, 'session_comment') && ~isempty(session.meta.session_comment)
                        sessionComment = char(session.meta.session_comment);
                    else
                        sessionComment = '';
                    end

                    if isfield(session, 'meta') && isfield(session.meta, 'analyst') && ~isempty(session.meta.analyst)
                        analyst = char(session.meta.analyst);
                    else
                        analyst = '';
                    end

                    if isfield(session, 'meta') && isfield(session.meta, 'processed_by') && ~isempty(session.meta.processed_by)
                        processed_by = char(session.meta.processed_by);
                    else
                        processed_by = '';
                    end

                    % Spot block
                    if ~isfield(session, 'spot') || isempty(session.spot)
                        continue
                    end

                    spotnames = session.spot.name;
                    nSpots = numel(spotnames);

                    % Require analyte_calibrator_assignment in selected sessions
                    if ~isfield(session.spot, 'analyte_calibrator_assignment') || ...
                            isempty(session.spot.analyte_calibrator_assignment)

                        sessionLabel = sessionName;

                        missingAssignmentSessions{end+1,1} = [char(projectName) ' / ' sessionLabel];
                        continue
                    end

                    assignments = session.spot.analyte_calibrator_assignment;
                    [assignmentMap, assignedCalNames] = buildAnalyteCalMap(assignments);

                    % --- Extract dynamic metadata for current session ---
                    sessionDynamicData = struct();
                    for f = 1:numel(dynamicMetaFields)
                        fieldName = dynamicMetaFields{f};
                        if isfield(session, 'meta') && isfield(session.meta, fieldName) && ~isempty(session.meta.(fieldName))
                            sessionDynamicData.(fieldName) = session.meta.(fieldName)(:);
                        else
                            sessionDynamicData.(fieldName) = repmat({''}, nSpots, 1);
                        end
                    end

                    % Type and CALs (backward compatibility, local only)
                    if isfield(session.spot, 'type') && ~isempty(session.spot.type)
                        type = string(session.spot.type(:));
                    else
                        type = strings(nSpots, 1);
                    end
                    type(type == "PRM") = "CAL";
                    type(type == "SRM") = "QCRM";

                    if isfield(session.spot, 'referencematerial') && ~isempty(session.spot.referencematerial)
                        referencematerial = string(session.spot.referencematerial(:));
                    else
                        referencematerial = strings(nSpots, 1);
                    end

                    % Session-local CAL list
                    cal_indices = (type == "CAL");
                    if any(cal_indices)
                        cal_materials = referencematerial(cal_indices);
                        validCALs = unique(cal_materials); % no 'stable' by design
                        validCALs = validCALs(~strcmp(validCALs, "None"));
                        validCALs = validCALs(~strcmp(validCALs, "Select Reference Material"));
                    else
                        validCALs = string.empty(0,1);
                    end

                    % Bracket IDs
                    if isfield(session.spot, 'bracket_id') && ~isempty(session.spot.bracket_id)
                        bracket_id = session.spot.bracket_id(:);
                        if ~isnumeric(bracket_id)
                            bracket_id = zeros(nSpots,1);
                        end
                        uniqueBracketIds = unique(bracket_id);
                        uniqueBracketIds = uniqueBracketIds(uniqueBracketIds ~= 0);
                    else
                        uniqueBracketIds = [];
                    end

                    % Integer time points
                    if isfield(session.spot, 'integer_time_points') && ~isempty(session.spot.integer_time_points)
                        integer_time_points = session.spot.integer_time_points(:);
                        if ~isnumeric(integer_time_points)
                            integer_time_points = NaN(nSpots,1);
                        end
                    else
                        integer_time_points = NaN(nSpots,1);
                    end

                    % Analyte names
                    if isfield(session.spot, 'analytes') && ~isempty(session.spot.analytes)
                        analyteNames = strtrim(string(session.spot.analytes(:)));
                    else
                        analyteNames = strings(0,1);
                    end

                    % Session-specific preferred isotopes (used only when remove masses is enabled)
                    sessionPreferredIsoMap = containers.Map('KeyType', 'char', 'ValueType', 'char');
                    if removeMassesPerSession
                        sessionPreferredIsoMap = getSessionPreferredIsotopeMap(session);
                    end

                    % If no CALs or no brackets, skip session
                    if isempty(validCALs) || isempty(uniqueBracketIds)
                        continue
                    end

                    % If all assigned analytes point to one CAL, metadata can be resolved.
                    % Otherwise these CAL-dependent metadata columns are ambiguous and filled with defaults.
                    singleAssignedCalIdx = [];
                    if numel(assignedCalNames) == 1
                        onlyCal = char(assignedCalNames(1));
                        singleAssignedCalIdx = find(strcmp(validCALs, onlyCal), 1);
                    end

                    % --- Only process blocks if checked in SpotMetaTree ---

                    % LA parameters
                    laserParamTable = table();
                    if isChecked("LA parameters")
                        if isfield(session, 'meta') && isfield(session.meta, 'laser_parameters')
                            laserParamStruct = session.meta.laser_parameters;
                            laserParamTable = struct_to_table(laserParamStruct);
                        else
                            laserParamTable = table();
                        end
                    end

                    % ICP-MS parameters
                    ICPMSParamTable = table();
                    if isChecked("ICP-MS parameters")
                        if isfield(session, 'meta') && isfield(session.meta, 'icp_ms_parameters')
                            ICPMSParamStruct = session.meta.icp_ms_parameters;
                            ICPMSParamTable = struct_to_table(ICPMSParamStruct);
                        else
                            ICPMSParamTable = table();
                        end
                    end

                    % Sensitivities
                    if isChecked("Sensitivities")
                        if isfield(session.spot, 'cal_sensitivities')
                            cal_sensitivities = session.spot.cal_sensitivities;
                        elseif isfield(session.spot, 'prm_sensitivities')
                            cal_sensitivities = session.spot.prm_sensitivities;
                        else
                            cal_sensitivities = table();
                        end
                        sensitivities = getNestedTable(session.spot, 'sensitivities', uniqueBracketIds, validCALs, nSpots, analyteNames);

                        % Apply cal_sensitivities patches to all CAL tables (same behavior as before)
                        if ~isempty(cal_sensitivities) && istable(cal_sensitivities)
                            for b = 1:numel(uniqueBracketIds)
                                for p = 1:numel(validCALs)
                                    sens_tbl = ensureAnalyteTable(sensitivities{b,p}, analyteNames, nSpots);
                                    common_cols = intersect(sens_tbl.Properties.VariableNames, cal_sensitivities.Properties.VariableNames);
                                    if ~isempty(common_cols)
                                        nRowsCommon = min(height(sens_tbl), height(cal_sensitivities));
                                        if nRowsCommon > 0
                                            nonzero_rows = any(table2array(cal_sensitivities(1:nRowsCommon, :)) ~= 0, 2);
                                            idxRows = find(nonzero_rows);
                                            for cc = 1:numel(common_cols)
                                                colName = common_cols{cc};
                                                sens_tbl{idxRows, colName} = cal_sensitivities{idxRows, colName};
                                            end
                                        end
                                    end
                                    sensitivities{b,p} = sens_tbl;
                                end
                            end
                        end
                    end

                    % Matrix/Host mass fractions
                    if isChecked("Matrix/Host mass fractions")
                        matrix_composition = getNestedTable(session.spot, 'matrix_composition', uniqueBracketIds, validCALs, nSpots, analyteNames);
                    end

                    % Matrix/Host limits of detection
                    if isChecked("Matrix/Host limits of detection") || isChecked("Matrix/Host mass fractions")
                        matrix_limits_of_detection = getNestedTable(session.spot, 'matrix_limits_of_detection', uniqueBracketIds, validCALs, nSpots, analyteNames);
                    end

                    % Inclusion blocks
                    if isChecked("Inclusion mass fractions")
                        inclusion_composition = getNestedTable(session.spot, 'inclusion_composition', uniqueBracketIds, validCALs, nSpots, analyteNames);
                    end
                    if isChecked("Inclusion limits of detection") || isChecked("Inclusion mass fractions") || isChecked("Mix mass fractions")
                        inclusion_limits_of_detection = getNestedTable(session.spot, 'inclusion_limits_of_detection', uniqueBracketIds, validCALs, nSpots, analyteNames);
                    end
                    if isChecked("Mix mass fractions")
                        mix_composition = getNestedTable(session.spot, 'mix_composition', uniqueBracketIds, validCALs, nSpots, analyteNames);
                    end

                    % Mixing ratios / unmixing metadata
                    if isChecked("Host-Inclusion-unmixing")

                        if isfield(session.spot, 'unmixing_via') && ~isempty(session.spot.unmixing_via)
                            unmixing_via = string(session.spot.unmixing_via(:));
                        else
                            unmixing_via = repmat("", nSpots, 1);
                        end
                        if isfield(session.spot, 'matrix_only_tracer') && ~isempty(session.spot.matrix_only_tracer)
                            matrix_only_tracer = string(session.spot.matrix_only_tracer(:));
                        else
                            matrix_only_tracer = repmat("", nSpots, 1);
                        end
                        if isfield(session.spot, 'matrix_from_spot') && ~isempty(session.spot.matrix_from_spot)
                            matrix_from_spot = string(session.spot.matrix_from_spot(:));
                        else
                            matrix_from_spot = repmat("", nSpots, 1);
                        end
                    end

                    % cps
                    if isfield(session.spot, 'bkgd_corrected_cps')
                        bkgdCps_mat = session.spot.bkgd_corrected_cps;
                    else
                        bkgdCps_mat = table('Size', [nSpots, numel(analyteNames)], ...
                            'VariableTypes', repmat("double", 1, numel(analyteNames)), ...
                            'VariableNames', cellstr(analyteNames));
                    end

                    if isChecked("Inclusion counts-per-sweep")
                        if isfield(session.spot, 'bkgd_corrected_cps_incl')
                            bkgdCps_incl = session.spot.bkgd_corrected_cps_incl;
                        else
                            bkgdCps_incl = array2table(zeros(nSpots, numel(analyteNames)), ...
                                'VariableNames', cellstr(analyteNames));
                        end
                    end

                    if isChecked("Mix counts-per-sweep")
                        if isfield(session.spot, 'bkgd_corrected_cps_mix')
                            bkgdCps_mix = session.spot.bkgd_corrected_cps_mix;
                        else
                            bkgdCps_mix = array2table(zeros(nSpots, numel(analyteNames)), ...
                                'VariableNames', cellstr(analyteNames));
                        end
                    end

                    % --- Core logic ---
                    % Rows are duplicated only by bracket and spot.
                    % CAL selection for analyte values is analyte-wise from analyte_calibrator_assignment.
                    for b = 1:numel(uniqueBracketIds)
                        % Metadata rows (nSpots per bracket)
                        for k = 1:nSpots
                            allProjects{end+1,1} = projectName;
                            allSessions{end+1,1} = sessionName;
                            allComments{end+1,1} = sessionComment;
                            allAnalysts{end+1,1} = analyst;
                            allProcessors{end+1,1} = processed_by;
                            alldates{end+1,1} = sessiondate;
                            allSpotnames{end+1,1} = spotnames{k};

                            for f = 1:numel(dynamicMetaFields)
                                fieldName = dynamicMetaFields{f};
                                currentColumn = sessionDynamicData.(fieldName);
                                if numel(currentColumn) >= k
                                    dynamicMetaData.(fieldName){end+1,1} = currentColumn{k};
                                else
                                    dynamicMetaData.(fieldName){end+1,1} = '';
                                end
                            end

                            if numel(type) >= k && ~ismissing(type(k))
                                allTypes{end+1,1} = type(k);
                            else
                                allTypes{end+1,1} = "";
                            end

                            if numel(referencematerial) >= k && ~ismissing(referencematerial(k))
                                allRefMaterials{end+1,1} = referencematerial(k);
                            else
                                allRefMaterials{end+1,1} = "";
                            end

                            allIntTimePoints{end+1,1} = integer_time_points(k);
                            allusedBracket_id{end+1,1} = uniqueBracketIds(b);

                            if isChecked("Host-Inclusion-unmixing")
                                if numel(unmixing_via) >= k
                                    allUnmixingVia{end+1,1} = unmixing_via(k);
                                else
                                    allUnmixingVia{end+1,1} = "";
                                end
                                if numel(matrix_only_tracer) >= k
                                    allMatrixOnlyTracer{end+1,1} = matrix_only_tracer(k);
                                else
                                    allMatrixOnlyTracer{end+1,1} = "";
                                end
                                if numel(matrix_from_spot) >= k
                                    allMatrixFromSpot{end+1,1} = matrix_from_spot(k);
                                else
                                    allMatrixFromSpot{end+1,1} = "";
                                end
                            end
                        end

                        % Numeric blocks per bracket using analyte-level CAL assignment
                        if isChecked("Sensitivities")
                            sensTblAssigned = buildAssignedAnalyteTable(sensitivities, b, assignmentMap, validCALs, analyteNames, nSpots);
                            sensTblAssigned = ensureAnalyteTable(sensTblAssigned, analyteNames, nSpots);
                            if removeMassesPerSession
                                sensTblAssigned = collapseIsotopeColumnsForSession(sensTblAssigned, sessionPreferredIsoMap);
                            end
                            allSensitivitiesRows{end+1,1} = sensTblAssigned;
                        end

                        if isChecked("Matrix/Host mass fractions")
                            matrixCompAssigned = buildAssignedAnalyteTable(matrix_composition, b, assignmentMap, validCALs, analyteNames, nSpots);
                            matrixCompAssigned = ensureAnalyteTable(matrixCompAssigned, analyteNames, nSpots);
                            if removeMassesPerSession
                                matrixCompAssigned = collapseIsotopeColumnsForSession(matrixCompAssigned, sessionPreferredIsoMap);
                            end
                            allmatrixcompRows{end+1,1} = matrixCompAssigned;
                        end

                        if isChecked("Matrix/Host limits of detection") || isChecked("Matrix/Host mass fractions")
                            matrixLodAssigned = buildAssignedAnalyteTable(matrix_limits_of_detection, b, assignmentMap, validCALs, analyteNames, nSpots);
                            matrixLodAssigned = ensureAnalyteTable(matrixLodAssigned, analyteNames, nSpots);
                            if removeMassesPerSession
                                matrixLodAssigned = collapseIsotopeColumnsForSession(matrixLodAssigned, sessionPreferredIsoMap);
                            end
                            allmatrixlodRows{end+1,1} = matrixLodAssigned;
                        end

                        if isChecked("Inclusion mass fractions")
                            inclusionCompAssigned = buildAssignedAnalyteTable(inclusion_composition, b, assignmentMap, validCALs, analyteNames, nSpots);
                            inclusionCompAssigned = ensureAnalyteTable(inclusionCompAssigned, analyteNames, nSpots);
                            if removeMassesPerSession
                                inclusionCompAssigned = collapseIsotopeColumnsForSession(inclusionCompAssigned, sessionPreferredIsoMap);
                            end
                            allinclcompRows{end+1,1} = inclusionCompAssigned;
                        end

                        if isChecked("Inclusion limits of detection") || isChecked("Inclusion mass fractions") || isChecked("Mix mass fractions")
                            inclusionLodAssigned = buildAssignedAnalyteTable(inclusion_limits_of_detection, b, assignmentMap, validCALs, analyteNames, nSpots);
                            inclusionLodAssigned = ensureAnalyteTable(inclusionLodAssigned, analyteNames, nSpots);
                            if removeMassesPerSession
                                inclusionLodAssigned = collapseIsotopeColumnsForSession(inclusionLodAssigned, sessionPreferredIsoMap);
                            end
                            allincllodRows{end+1,1} = inclusionLodAssigned;
                        end

                        if isChecked("Mix mass fractions")
                            mixCompAssigned = buildAssignedAnalyteTable(mix_composition, b, assignmentMap, validCALs, analyteNames, nSpots);
                            mixCompAssigned = ensureAnalyteTable(mixCompAssigned, analyteNames, nSpots);
                            if removeMassesPerSession
                                mixCompAssigned = collapseIsotopeColumnsForSession(mixCompAssigned, sessionPreferredIsoMap);
                            end
                            allmixcompRows{end+1,1} = mixCompAssigned;
                        end

                        % cps
                        bkgdTblMat = ensureAnalyteTable(bkgdCps_mat, analyteNames, nSpots);
                        if removeMassesPerSession
                            bkgdTblMat = collapseIsotopeColumnsForSession(bkgdTblMat, sessionPreferredIsoMap);
                        end
                        allbkgdCpsRows_mat{end+1,1} = bkgdTblMat;

                        if isChecked("Inclusion counts-per-sweep")
                            bkgdTblIncl = ensureAnalyteTable(bkgdCps_incl, analyteNames, nSpots);
                            if removeMassesPerSession
                                bkgdTblIncl = collapseIsotopeColumnsForSession(bkgdTblIncl, sessionPreferredIsoMap);
                            end
                            allbkgdCpsRows_incl{end+1,1} = bkgdTblIncl;
                        end
                        if isChecked("Mix counts-per-sweep")
                            bkgdTblMix = ensureAnalyteTable(bkgdCps_mix, analyteNames, nSpots);
                            if removeMassesPerSession
                                bkgdTblMix = collapseIsotopeColumnsForSession(bkgdTblMix, sessionPreferredIsoMap);
                            end
                            allbkgdCpsRows_mix{end+1,1} = bkgdTblMix;
                        end

                        % LA/ICP parameter tables (one per bracket)
                        if isChecked("LA parameters")
                            allLaserParamTables{end+1,1} = laserParamTable;
                        end
                        if isChecked("ICP-MS parameters")
                            allICPMSParamTables{end+1,1} = ICPMSParamTable;
                        end
                    end
                end
            end

            if ~isempty(missingAssignmentSessions)
                missingAssignmentSessions = unique(missingAssignmentSessions);

                if numel(missingAssignmentSessions) == 1
                    msg = sprintf(['The analyte_calibrator_assignment is missing in session:\n\n%s\n\n' ...
                        'This session was skipped.'], missingAssignmentSessions{1});
                else
                    msg = sprintf(['The analyte_calibrator_assignment is missing in these sessions:\n\n%s\n\n' ...
                        'These sessions were skipped.'], strjoin(missingAssignmentSessions, newline));
                end

                uialert(app.TableBuilderUIFigure, msg, ...
                    'Missing analyte-calibrator assignment', ...
                    'Icon', 'warning');
            end

            % --- Build Metadata Table (always included) ---
            Metadata_Table = table(allProjects, allSessions, allComments, allAnalysts, allProcessors, alldates, allSpotnames, ...
                allTypes, allRefMaterials, allIntTimePoints, allusedBracket_id, ...
                'VariableNames', {'Project', 'Session', 'Comment','Analyst', 'Processed by', 'Date', 'Spotname', ...
                'Type', 'Reference_Material', 'Integer_Time_Points', 'used_bracket_id'});

            % Add dynamic metadata columns
            for f = 1:numel(dynamicMetaFields)
                fieldName = dynamicMetaFields{f};
                Metadata_Table.(fieldName) = dynamicMetaData.(fieldName);
            end

            if isChecked("Host-Inclusion-unmixing")
                Metadata_Table.Unmixing_Via = allUnmixingVia;
                Metadata_Table.Matrix_Only_Tracer = allMatrixOnlyTracer;
                Metadata_Table.Matrix_From_Spot = allMatrixFromSpot;
            end

            % --- Build selected numeric/parameter blocks ---
            tableBlocks = {Metadata_Table};

            if isChecked("Matrix/Host mass fractions")
                allmatrixlodTable = buildNumericBlock(allmatrixlodRows, 'Matrix_LOD');
                allmatrixlodTable = renameAnalyteColumns(allmatrixlodTable, '_mat_lod');
                if ismember('Data_Source', allmatrixlodTable.Properties.VariableNames)
                    allmatrixlodTable.Data_Source = [];
                end

                allmatrixcompTable = buildNumericBlock(allmatrixcompRows, 'Matrix_Composition');
                allmatrixcompTable = renameAnalyteColumns(allmatrixcompTable, '_mat_ugg');
                if ismember('Data_Source', allmatrixcompTable.Properties.VariableNames)
                    allmatrixcompTable.Data_Source = [];
                end

                allmatrixcompTable = applyLODFiltering(allmatrixcompTable, allmatrixlodTable, '_mat_ugg', '_mat_lod');
                tableBlocks{end+1} = allmatrixcompTable;
            end

            if isChecked("Inclusion mass fractions")
                allinclusionlodTable = buildNumericBlock(allincllodRows, 'Inclusion_LOD');
                allinclusionlodTable = renameAnalyteColumns(allinclusionlodTable, '_incl_lod');
                if ismember('Data_Source', allinclusionlodTable.Properties.VariableNames)
                    allinclusionlodTable.Data_Source = [];
                end

                allinclusioncompTable = buildNumericBlock(allinclcompRows, 'Inclusion_Composition');
                allinclusioncompTable = renameAnalyteColumns(allinclusioncompTable, '_incl_ugg');
                if ismember('Data_Source', allinclusioncompTable.Properties.VariableNames)
                    allinclusioncompTable.Data_Source = [];
                end

                allinclusioncompTable = applyLODFiltering(allinclusioncompTable, allinclusionlodTable, '_incl_ugg', '_incl_lod');
                tableBlocks{end+1} = allinclusioncompTable;
            end

            if isChecked("Mix mass fractions")
                allinclusionlodTable = buildNumericBlock(allincllodRows, 'Inclusion_LOD');
                allinclusionlodTable = renameAnalyteColumns(allinclusionlodTable, '_incl_lod');
                if ismember('Data_Source', allinclusionlodTable.Properties.VariableNames)
                    allinclusionlodTable.Data_Source = [];
                end

                allmixcompTable = buildNumericBlock(allmixcompRows, 'Mix_Composition');
                allmixcompTable = renameAnalyteColumns(allmixcompTable, '_mix_ugg');
                if ismember('Data_Source', allmixcompTable.Properties.VariableNames)
                    allmixcompTable.Data_Source = [];
                end

                allmixcompTable = applyLODFiltering(allmixcompTable, allinclusionlodTable, '_mix_ugg', '_incl_lod');
                tableBlocks{end+1} = allmixcompTable;
            end

            if isChecked("Matrix/Host limits of detection")
                allmatrixlodTable = buildNumericBlock(allmatrixlodRows, 'Matrix_LOD');
                allmatrixlodTable = renameAnalyteColumns(allmatrixlodTable, '_mat_lod');
                if ismember('Data_Source', allmatrixlodTable.Properties.VariableNames)
                    allmatrixlodTable.Data_Source = [];
                end
                tableBlocks{end+1} = allmatrixlodTable;
            end

            if isChecked("Inclusion limits of detection")
                allinclusionlodTable = buildNumericBlock(allincllodRows, 'Inclusion_LOD');
                allinclusionlodTable = renameAnalyteColumns(allinclusionlodTable, '_incl_lod');
                if ismember('Data_Source', allinclusionlodTable.Properties.VariableNames)
                    allinclusionlodTable.Data_Source = [];
                end
                tableBlocks{end+1} = allinclusionlodTable;
            end

            if isChecked("Matrix/Host counts-per-sweep")
                allbkgdCpsTable_mat = buildNumericBlock(allbkgdCpsRows_mat, 'Bkgd_Corrected_CPS_mat');
                allbkgdCpsTable_mat = renameAnalyteColumns(allbkgdCpsTable_mat, '_mat_cps');
                if ismember('Data_Source', allbkgdCpsTable_mat.Properties.VariableNames)
                    allbkgdCpsTable_mat.Data_Source = [];
                end
                tableBlocks{end+1} = allbkgdCpsTable_mat;
            end

            if isChecked("Inclusion counts-per-sweep")
                allbkgdCpsTable_incl = buildNumericBlock(allbkgdCpsRows_incl, 'Bkgd_Corrected_CPS_incl');
                allbkgdCpsTable_incl = renameAnalyteColumns(allbkgdCpsTable_incl, '_incl_cps');
                if ismember('Data_Source', allbkgdCpsTable_incl.Properties.VariableNames)
                    allbkgdCpsTable_incl.Data_Source = [];
                end
                tableBlocks{end+1} = allbkgdCpsTable_incl;
            end

            if isChecked("Mix counts-per-sweep")
                allbkgdCpsTable_mix = buildNumericBlock(allbkgdCpsRows_mix, 'Bkgd_Corrected_CPS_mix');
                allbkgdCpsTable_mix = renameAnalyteColumns(allbkgdCpsTable_mix, '_mix_cps');
                if ismember('Data_Source', allbkgdCpsTable_mix.Properties.VariableNames)
                    allbkgdCpsTable_mix.Data_Source = [];
                end
                tableBlocks{end+1} = allbkgdCpsTable_mix;
            end

            if isChecked("Sensitivities")
                allsensTable = buildNumericBlock(allSensitivitiesRows, 'Sensitivities');
                allsensTable = renameAnalyteColumns(allsensTable, '_sens');
                if ismember('Data_Source', allsensTable.Properties.VariableNames)
                    allsensTable.Data_Source = [];
                end
                tableBlocks{end+1} = allsensTable;
            end

            if isChecked("LA parameters")
                for i = 1:numel(allLaserParamTables)
                    if isempty(allLaserParamTables{i}) || height(allLaserParamTables{i}) == 0
                        nRows = height(allbkgdCpsRows_mat{i});
                        for j = 1:numel(allLaserParamTables)
                            if ~isempty(allLaserParamTables{j}) && height(allLaserParamTables{j}) > 0
                                refTable = allLaserParamTables{j};
                                varNames = refTable.Properties.VariableNames;
                                varTypes = varfun(@class, refTable, 'OutputFormat', 'cell');
                                emptyData = cell(1, numel(varNames));
                                for k = 1:numel(varNames)
                                    switch varTypes{k}
                                        case {'double', 'single'}
                                            emptyData{k} = zeros(nRows,1);
                                        case {'cell'}
                                            emptyData{k} = cell(nRows,1);
                                        case {'char', 'string'}
                                            emptyData{k} = repmat({''}, nRows,1);
                                        case {'datetime'}
                                            emptyData{k} = NaT(nRows,1);
                                        case {'logical'}
                                            emptyData{k} = false(nRows,1);
                                        otherwise
                                            emptyData{k} = cell(nRows,1);
                                    end
                                end
                                allLaserParamTables{i} = table(emptyData{:}, 'VariableNames', varNames);
                                break;
                            end
                        end
                    end
                end

                allLaserParamTables = harmonizeTableColumns(allLaserParamTables);
                allLaserParamTables = vertcat(allLaserParamTables{:});
                tableBlocks{end+1} = allLaserParamTables;
            end

            if isChecked("ICP-MS parameters")
                for i = 1:numel(allICPMSParamTables)
                    nRows = height(allbkgdCpsRows_mat{i});
                    tbl = allICPMSParamTables{i};

                    if isempty(tbl) || height(tbl) == 0
                        for j = 1:numel(allICPMSParamTables)
                            if ~isempty(allICPMSParamTables{j}) && height(allICPMSParamTables{j}) > 0
                                refTable = allICPMSParamTables{j};
                                varNames = refTable.Properties.VariableNames;
                                emptyData = cell(1, numel(varNames));
                                for k = 1:numel(varNames)
                                    refCol = refTable.(varNames{k});
                                    refType = class(refCol);
                                    if strcmp(refType, 'cell')
                                        firstNonEmpty = find(~cellfun(@isempty, refCol), 1);
                                        if isempty(firstNonEmpty)
                                            emptyData{k} = repmat({''}, nRows, 1);
                                        else
                                            content = refCol{firstNonEmpty};
                                            if ischar(content) || isstring(content)
                                                emptyData{k} = repmat({''}, nRows, 1);
                                            elseif isnumeric(content)
                                                emptyData{k} = num2cell(zeros(nRows, 1));
                                            else
                                                emptyData{k} = cell(nRows, 1);
                                            end
                                        end
                                    elseif strcmp(refType, 'double') || strcmp(refType, 'single')
                                        emptyData{k} = zeros(nRows, 1);
                                    elseif strcmp(refType, 'char') || strcmp(refType, 'string')
                                        emptyData{k} = repmat({''}, nRows, 1);
                                    elseif strcmp(refType, 'datetime')
                                        emptyData{k} = NaT(nRows, 1);
                                    elseif strcmp(refType, 'logical')
                                        emptyData{k} = false(nRows, 1);
                                    else
                                        emptyData{k} = cell(nRows, 1);
                                    end
                                end
                                tbl = table(emptyData{:}, 'VariableNames', varNames);
                                break;
                            end
                        end
                    elseif height(tbl) == 1 && nRows > 1
                        tbl = tbl(ones(nRows,1), :);
                    end

                    allICPMSParamTables{i} = tbl;
                end

                allICPMSParamTables = harmonizeTableColumns(allICPMSParamTables);
                allICPMSParamTables = vertcat(allICPMSParamTables{:});
                tableBlocks{end+1} = allICPMSParamTables;
            end

            % --- Concatenate selected blocks ---
            Base_Table = [tableBlocks{:}];

            % --- Normalize textual columns and replace numeric zeros with NaN ---
            if ~isempty(Base_Table)
                if ismember('Type', Base_Table.Properties.VariableNames)
                    Base_Table.Type = normalizeToCellstr(Base_Table.Type);
                end
                if ismember('Material', Base_Table.Properties.VariableNames)
                    Base_Table.Material = normalizeToCellstr(Base_Table.Material);
                end
                if ismember('Reference_Material', Base_Table.Properties.VariableNames)
                    Base_Table.Reference_Material = normalizeToCellstr(Base_Table.Reference_Material);
                end

                numericCols = varfun(@isnumeric, Base_Table, 'OutputFormat', 'uniform');
                if any(numericCols)
                    X = Base_Table{:, numericCols};
                    X(X == 0) = NaN;
                    Base_Table{:, numericCols} = X;
                end
            end

            % Replace 0 with NaN in LOD-filtered composition columns (cell arrays with mixed numeric/string)
            lodFilteredSuffixes = {'_mat_ugg', '_incl_ugg', '_mix_ugg'};
            for colIdx = 1:width(Base_Table)
                colName = Base_Table.Properties.VariableNames{colIdx};
                if any(contains(colName, lodFilteredSuffixes))
                    col = Base_Table.(colName);
                    if iscell(col)
                        for rowIdx = 1:numel(col)
                            if isnumeric(col{rowIdx}) && col{rowIdx} == 0
                                col{rowIdx} = NaN;
                            end
                        end
                        Base_Table.(colName) = col;
                    end
                end
            end

            % Replace empty doubles and 0 values in cell arrays with NaN
            for colIdx = 1:width(Base_Table)
                col = Base_Table.(colIdx);
                if iscell(col)
                    for rowIdx = 1:numel(col)
                        cellVal = col{rowIdx};
                        if isnumeric(cellVal) && isempty(cellVal)
                            col{rowIdx} = NaN;
                        elseif isnumeric(cellVal) && cellVal == 0
                            col{rowIdx} = NaN;
                        end
                    end
                    Base_Table{:, colIdx} = col;
                end
            end

            % --- Row cleanup based on analyte availability (row-only; never drops columns) ---
            if ~isempty(Base_Table)
                varNames = Base_Table.Properties.VariableNames;

                % Analyte columns with allowed output suffixes.
                % Supports both mass-containing names (e.g., Na23_mat_ugg) and mass-free names (e.g., Na_mat_ugg).
                analyteNamePattern = '[A-Z][a-z]?(?:\d+)?';
                analyteSuffixPattern = '(?:_mat_ugg|_incl_ugg|_mix_ugg|_mat_lod|_incl_lod|_mat_cps|_incl_cps|_mix_cps|_sens)';
                isAnalyteCol = ~cellfun('isempty', regexp(varNames, ...
                    ['^' analyteNamePattern analyteSuffixPattern '$'], 'once'));

                analyteCols = varNames(isAnalyteCol);
                sensCols = analyteCols(endsWith(analyteCols, '_sens'));
                nonSensCols = setdiff(analyteCols, sensCols, 'stable');

                hasSens = ~isempty(sensCols);
                hasNonSens = ~isempty(nonSensCols);

                % Cases:
                % 1) no analyte columns -> no cleanup
                % 2) cleanup applies even if only _sens is in the table
                if ~isempty(analyteCols)
                    nRows = height(Base_Table);
                    removeMask = false(nRows,1);

                    % Core rule: remove row if all analyte columns are missing
                    allAnalyteMissing = true(nRows,1);
                    for c = 1:numel(analyteCols)
                        allAnalyteMissing = allAnalyteMissing & isMissingByRow(Base_Table.(analyteCols{c}));
                    end
                    removeMask = removeMask | allAnalyteMissing;

                    % New rule: remove row if all _sens columns are missing
                    if hasSens
                        allSensMissing = true(nRows, 1);
                        for c = 1:numel(sensCols)
                            allSensMissing = allSensMissing & isMissingByRow(Base_Table.(sensCols{c}));
                        end
                        removeMask = removeMask | allSensMissing;
                    end

                    % Extra non-CAL rule: only when both _sens and non-_sens exist
                    if hasSens && hasNonSens && ismember('Type', varNames)
                        allNonSensMissing = true(nRows,1);
                        for c = 1:numel(nonSensCols)
                            allNonSensMissing = allNonSensMissing & isMissingByRow(Base_Table.(nonSensCols{c}));
                        end

                        anySensPresent = false(nRows,1);
                        for c = 1:numel(sensCols)
                            anySensPresent = anySensPresent | ~isMissingByRow(Base_Table.(sensCols{c}));
                        end

                        isCal = strcmpi(strtrim(string(Base_Table.Type)), "CAL");
                        removeMask = removeMask | (~isCal & allNonSensMissing & anySensPresent);
                    end

                    Base_Table(removeMask, :) = [];
                end
            end

            % ---------------- Helpers ----------------

            function preferredIsoMap = getSessionPreferredIsotopeMap(session)
                preferredIsoMap = containers.Map('KeyType', 'char', 'ValueType', 'char');

                if ~isfield(session, 'oxides') || ~isstruct(session.oxides)
                    return
                end
                if ~isfield(session.oxides, 'preferred_isotopes') || isempty(session.oxides.preferred_isotopes)
                    return
                end

                rawPref = session.oxides.preferred_isotopes;

                if iscell(rawPref)
                    prefList = string(rawPref(:));
                elseif isstring(rawPref)
                    prefList = rawPref(:);
                elseif ischar(rawPref)
                    prefList = string({rawPref});
                else
                    try
                        prefList = string(rawPref(:));
                    catch
                        return
                    end
                end

                for ii = 1:numel(prefList)
                    isoName = strtrim(prefList(ii));
                    if ismissing(isoName) || strlength(isoName) == 0
                        continue
                    end

                    tok = regexp(char(isoName), '^([A-Z][a-z]?)(\d+)$', 'tokens', 'once');
                    if isempty(tok)
                        continue
                    end

                    elementName = tok{1};
                    preferredIsoMap(elementName) = char(isoName);
                end
            end

            function T = collapseIsotopeColumnsForSession(T, preferredIsoMap)
                if isempty(T) || ~istable(T) || width(T) == 0
                    return
                end

                varNames = T.Properties.VariableNames;
                isoPattern = '^([A-Z][a-z]?)(\d+)$';

                groupedCols = containers.Map('KeyType', 'char', 'ValueType', 'any');
                for ii = 1:numel(varNames)
                    colName = varNames{ii};
                    tok = regexp(colName, isoPattern, 'tokens', 'once');
                    if isempty(tok)
                        continue
                    end
                    elementName = tok{1};

                    if ~isKey(groupedCols, elementName)
                        groupedCols(elementName) = {colName};
                    else
                        tmp = groupedCols(elementName);
                        tmp{end+1} = colName;
                        groupedCols(elementName) = tmp;
                    end
                end

                if groupedCols.Count == 0
                    return
                end

                elements = keys(groupedCols);
                nRows = height(T);

                for e = 1:numel(elements)
                    elementName = elements{e};
                    cols = groupedCols(elementName);

                    % Keep only columns that still exist (table changes during loop)
                    cols = cols(ismember(cols, T.Properties.VariableNames));
                    if isempty(cols)
                        continue
                    end

                    targetName = elementName;

                    if numel(cols) == 1
                        srcName = cols{1};

                        if strcmp(srcName, targetName)
                            continue
                        end

                        if ismember(targetName, T.Properties.VariableNames)
                            srcVec = coerceNumericVector(T.(srcName), nRows);
                            dstVec = coerceNumericVector(T.(targetName), nRows);

                            dstMissing = isnan(dstVec) | dstVec == 0;
                            srcPresent = ~(isnan(srcVec) | srcVec == 0);
                            fillMask = dstMissing & srcPresent;
                            dstVec(fillMask) = srcVec(fillMask);

                            T.(targetName) = dstVec;
                            T(:, srcName) = [];
                        else
                            T = renamevars(T, srcName, targetName);
                        end

                        continue
                    end

                    prefCol = '';
                    if isKey(preferredIsoMap, elementName)
                        prefIso = preferredIsoMap(elementName);
                        if any(strcmp(cols, prefIso))
                            prefCol = prefIso;
                        end
                    end

                    if ~isempty(prefCol)
                        merged = coerceNumericVector(T.(prefCol), nRows);
                    else
                        merged = NaN(nRows, 1);
                    end

                    mergedMissing = isnan(merged) | merged == 0;

                    for c = 1:numel(cols)
                        colName = cols{c};
                        srcVec = coerceNumericVector(T.(colName), nRows);
                        srcPresent = ~(isnan(srcVec) | srcVec == 0);

                        fillMask = mergedMissing & srcPresent;
                        if any(fillMask)
                            merged(fillMask) = srcVec(fillMask);
                            mergedMissing(fillMask) = false;
                        end

                        if ~any(mergedMissing)
                            break
                        end
                    end

                    if ismember(targetName, T.Properties.VariableNames) && ~any(strcmp(cols, targetName))
                        dstVec = coerceNumericVector(T.(targetName), nRows);
                        dstMissing = isnan(dstVec) | dstVec == 0;
                        mergedPresent = ~(isnan(merged) | merged == 0);
                        fillMask = dstMissing & mergedPresent;
                        dstVec(fillMask) = merged(fillMask);
                        merged = dstVec;
                    end

                    if ismember(targetName, T.Properties.VariableNames)
                        T.(targetName) = merged;
                    else
                        firstPos = find(ismember(T.Properties.VariableNames, cols), 1, 'first');
                        if isempty(firstPos)
                            T.(targetName) = merged;
                        else
                            T = addvars(T, merged, 'Before', firstPos, 'NewVariableNames', targetName);
                        end
                    end

                    colsToDelete = cols;
                    colsToDelete(strcmp(colsToDelete, targetName)) = [];
                    if ~isempty(colsToDelete)
                        T(:, colsToDelete) = [];
                    end
                end
            end

            function missingMask = isMissingByRow(col)
                if isnumeric(col)
                    missingMask = isnan(col);

                elseif isstring(col)
                    missingMask = ismissing(col) | strlength(strtrim(col)) == 0;

                elseif ischar(col)
                    missingMask = cellfun(@(x) isempty(strtrim(x)), cellstr(col));

                elseif iscell(col)
                    n = numel(col);
                    missingMask = false(n,1);
                    for r = 1:n
                        v = col{r};

                        if isempty(v)
                            missingMask(r) = true;

                        elseif isnumeric(v)
                            missingMask(r) = isscalar(v) && isnan(v);

                        elseif isstring(v)
                            missingMask(r) = all(ismissing(v)) || all(strlength(strtrim(v)) == 0);

                        elseif ischar(v)
                            missingMask(r) = isempty(strtrim(v));

                        else
                            % Unsupported object types are treated as present
                            missingMask(r) = false;
                        end
                    end

                else
                    % Fallback for unexpected column types
                    missingMask = false(numel(col),1);
                end

                missingMask = missingMask(:);
            end

            function [assignmentMap, assignedCalList] = buildAnalyteCalMap(assignTbl)
                assignmentMap = containers.Map('KeyType', 'char', 'ValueType', 'char');
                assignedCalList = strings(0,1);

                if isempty(assignTbl)
                    return
                end

                if istable(assignTbl)
                    if width(assignTbl) < 2
                        return
                    end
                    analyteCol = string(assignTbl{:,1});
                    calCol = string(assignTbl{:,2});
                elseif iscell(assignTbl)
                    if size(assignTbl,2) < 2
                        return
                    end
                    analyteCol = string(assignTbl(:,1));
                    calCol = string(assignTbl(:,2));
                else
                    return
                end

                analyteCol = strtrim(analyteCol(:));
                calCol = strtrim(calCol(:));

                valid = strlength(analyteCol) > 0 & strlength(calCol) > 0;
                valid = valid & calCol ~= "None" & calCol ~= "Select Reference Material";

                assignedCalList = unique(calCol(valid)); % intentionally no 'stable'

                idx = find(valid);
                for ii = 1:numel(idx)
                    assignmentMap(char(analyteCol(idx(ii)))) = char(calCol(idx(ii)));
                end
            end

            function assignedTbl = buildAssignedAnalyteTable(nestedCell, b, assignmentMap_, validCALs_, analyteNames_, nRows_)
                assignedTbl = table('Size', [nRows_, numel(analyteNames_)], ...
                    'VariableTypes', repmat("double", 1, numel(analyteNames_)), ...
                    'VariableNames', cellstr(analyteNames_));

                if isempty(nestedCell) || size(nestedCell,1) < b
                    return
                end

                for a = 1:numel(analyteNames_)
                    analyteName = char(analyteNames_(a));
                    outCol = zeros(nRows_,1);

                    if isKey(assignmentMap_, analyteName)
                        calName = assignmentMap_(analyteName);
                        calIdx = find(strcmp(validCALs_, calName), 1); % same pattern as update_assigned_table

                        if ~isempty(calIdx) && calIdx <= size(nestedCell,2)
                            srcTab = nestedCell{b, calIdx};
                            if istable(srcTab) && ismember(analyteName, srcTab.Properties.VariableNames)
                                srcCol = coerceNumericVector(srcTab.(analyteName), nRows_);
                                nCopy = min(numel(srcCol), nRows_);
                                outCol(1:nCopy) = srcCol(1:nCopy);
                            end
                        end
                    end

                    assignedTbl.(analyteName) = outCol;
                end
            end

            function vec = coerceNumericVector(vec, nRows_)
                if iscell(vec)
                    tmp = zeros(numel(vec), 1);
                    for ii = 1:numel(vec)
                        v = vec{ii};
                        if isnumeric(v) && isscalar(v) && ~isempty(v)
                            tmp(ii) = v;
                        else
                            tmp(ii) = 0;
                        end
                    end
                    vec = tmp;
                end

                if ~isnumeric(vec)
                    try
                        vec = double(vec);
                    catch
                        try
                            vec = str2double(string(vec));
                            vec(isnan(vec)) = 0;
                        catch
                            vec = zeros(nRows_, 1);
                        end
                    end
                end

                vec = vec(:);
                if isempty(vec)
                    vec = zeros(nRows_, 1);
                end
            end

            function val = getNestedValue(C, b, p, k, defaultVal)
                val = defaultVal;
                try
                    if iscell(C) && numel(C) >= (p-1)*size(C,1)+b && ~isempty(C)
                        X = C{b,p};
                        if iscell(X)
                            if numel(X) >= k && ~isempty(X{k})
                                val = X{k};
                            end
                        elseif isstring(X) || ischar(X)
                            if numel(X) >= k
                                val = X(k);
                            end
                        elseif isnumeric(X)
                            if numel(X) >= k
                                val = X(k);
                            end
                        end
                    end
                catch
                    val = defaultVal;
                end
            end

            function T = ensureAnalyteTable(T, analyteNames_, nRows_)
                if ~istable(T) || isempty(T)
                    T = table('Size', [nRows_, numel(analyteNames_)], ...
                        'VariableTypes', repmat("double", 1, numel(analyteNames_)), ...
                        'VariableNames', cellstr(analyteNames_));
                    return
                end

                for v = 1:width(T)
                    if ~isnumeric(T{:,v})
                        try
                            T{:,v} = double(T{:,v});
                        catch
                            T{:,v} = zeros(height(T),1);
                        end
                    end
                end

                if height(T) ~= nRows_
                    headers = T.Properties.VariableNames;
                    TT = table('Size', [nRows_, numel(headers)], ...
                        'VariableTypes', repmat("double", 1, numel(headers)), ...
                        'VariableNames', headers);
                    nCopy = min(height(T), nRows_);
                    if nCopy > 0
                        TT(1:nCopy, :) = T(1:nCopy, :);
                    end
                    T = TT;
                end
            end

            function sortedHeaders = getSortedHeaders(rowsCell)
                if isempty(rowsCell)
                    sortedHeaders = {};
                    return
                end
                isT = cellfun(@istable, rowsCell);
                if ~any(isT)
                    sortedHeaders = {};
                    return
                end
                headersCell = cellfun(@(t) t.Properties.VariableNames, rowsCell(isT), 'UniformOutput', false);
                if isempty(headersCell)
                    sortedHeaders = {};
                    return
                end

                allHeaders = unique([headersCell{:}], 'stable');
                nums = cellfun(@localExtractNum, allHeaders);

                idx = (1:numel(allHeaders))';
                sortMat = [isnan(nums(:)), nums(:), idx];
                [~, sortIdx] = sortrows(sortMat, [1 2 3]);

                sortedHeaders = allHeaders(sortIdx);
            end

            function n = localExtractNum(h)
                tok = regexp(h, '\d+', 'match', 'once');
                if isempty(tok)
                    n = NaN;
                else
                    n = str2double(tok);
                end
            end

            function tableWithAllColumns = addMissingColumns(inputTable, targetHeaders)
                if isempty(targetHeaders)
                    tableWithAllColumns = inputTable;
                    return
                end
                missingCols = setdiff(targetHeaders, inputTable.Properties.VariableNames, 'stable');
                if isempty(missingCols)
                    tableWithAllColumns = inputTable(:, targetHeaders);
                    return
                end
                missingData = zeros(height(inputTable), numel(missingCols));
                missingTable = array2table(missingData, 'VariableNames', missingCols);
                tableWithAllColumns = [inputTable missingTable];
                tableWithAllColumns = tableWithAllColumns(:, targetHeaders);
            end

            function outTable = buildNumericBlock(rowsCell, label)
                if isempty(rowsCell)
                    outTable = table();
                    return
                end
                headers = getSortedHeaders(rowsCell);
                if ~isempty(rowsCell)
                    rowsCell = cellfun(@(t) addMissingColumns(t, headers), rowsCell, 'UniformOutput', false);
                end
                if isempty(rowsCell)
                    outTable = table();
                    return
                end
                outTable = vertcat(rowsCell{:});
                if isempty(outTable)
                    return
                end
                outTable = addvars(outTable, repmat({label}, height(outTable), 1), ...
                    'Before', 1, 'NewVariableNames', 'Data_Source');
            end

            function T = renameAnalyteColumns(T, suffix)
                if isempty(T)
                    return
                end
                metaCols = {'Data_Source'};
                for v = 1:width(T)
                    if ~ismember(T.Properties.VariableNames{v}, metaCols)
                        T.Properties.VariableNames{v} = [T.Properties.VariableNames{v} suffix];
                    end
                end
            end

            function T = normalizeToCellstr(T)
                if istable(T) || isstruct(T)
                    return
                end
                if iscell(T)
                    try
                        T = cellfun(@char, T, 'UniformOutput', false);
                    catch
                        T = cellfun(@(x) char(string(x)), T, 'UniformOutput', false);
                    end
                else
                    T = cellstr(string(T));
                end
            end

            function C = getNestedField(spotStruct, fieldName, uniqueBracketIds_, unique_CALs_, nSpots_, defaultVal)
                if isfield(spotStruct, fieldName)
                    C = spotStruct.(fieldName);
                else
                    C = cell(numel(uniqueBracketIds_), numel(unique_CALs_));
                    for b = 1:numel(uniqueBracketIds_)
                        for p = 1:numel(unique_CALs_)
                            if ischar(defaultVal) || isstring(defaultVal)
                                C{b, p} = repmat({defaultVal}, nSpots_, 1);
                            else
                                C{b, p} = repmat(defaultVal, nSpots_, 1);
                            end
                        end
                    end
                end
            end

            function C = getNestedTable(spotStruct, fieldName, uniqueBracketIds_, unique_CALs_, nSpots_, analyteNames_)
                if isfield(spotStruct, fieldName)
                    C = spotStruct.(fieldName);
                else
                    C = cell(numel(uniqueBracketIds_), numel(unique_CALs_));
                    for b = 1:numel(uniqueBracketIds_)
                        for p = 1:numel(unique_CALs_)
                            C{b, p} = table('Size', [nSpots_, numel(analyteNames_)], ...
                                'VariableTypes', repmat("double", 1, numel(analyteNames_)), ...
                                'VariableNames', cellstr(analyteNames_));
                        end
                    end
                end
            end

            function C = getNestedMixingRatio(spotStruct, fieldName, uniqueBracketIds_, unique_CALs_, nSpots_)
                if isfield(spotStruct, fieldName)
                    C = spotStruct.(fieldName);
                else
                    C = cell(numel(uniqueBracketIds_), numel(unique_CALs_));
                    for b = 1:numel(uniqueBracketIds_)
                        for p = 1:numel(unique_CALs_)
                            C{b, p} = repmat({NaN}, nSpots_, 1);
                        end
                    end
                end
            end

            function val = getNestedMixingRatioValue(C, b, p, k)
                val = [];
                try
                    if iscell(C) && numel(C) >= (p-1)*size(C,1)+b && ~isempty(C)
                        X = C{b,p};
                        if iscell(X)
                            if numel(X) >= k && ~isempty(X{k})
                                val = X{k};
                            else
                                val = NaN;
                            end
                        elseif isnumeric(X)
                            if numel(X) >= k
                                val = X(k);
                            else
                                val = NaN;
                            end
                        end
                    end
                catch
                    val = NaN;
                end
            end

            function T = struct_to_table(S)
                if isempty(S)
                    T = table();
                    return
                end
                fields = fieldnames(S);
                lens = zeros(numel(fields),1);
                for o = 1:numel(fields)
                    col = S.(fields{o});
                    if ischar(col) || (isstring(col) && isscalar(col))
                        lens(o) = 1;
                    else
                        lens(o) = numel(col);
                    end
                end
                n = max(lens);
                T = table();
                for o = 1:numel(fields)
                    col = S.(fields{o});
                    if isempty(col)
                        col = nan(n,1);
                    elseif ischar(col) || (isstring(col) && isscalar(col))
                        col = repmat({col}, n, 1);
                    elseif iscell(col) && numel(col) == 1 && n > 1
                        col = repmat(col, n, 1);
                    elseif isnumeric(col) && numel(col) == 1 && n > 1
                        col = repmat(col, n, 1);
                    elseif numel(col) ~= n
                        error('Field %s has %d elements, expected %d.', fields{o}, numel(col), n);
                    end
                    if isrow(col) && ~ischar(col)
                        col = col';
                    end
                    T.(fields{o}) = col;
                end
            end

            function harmonizedTables = harmonizeTableColumns(tableCell)
                if isempty(tableCell)
                    harmonizedTables = tableCell;
                    return
                end

                allVarNames = {};
                for i = 1:numel(tableCell)
                    if ~isempty(tableCell{i}) && istable(tableCell{i})
                        allVarNames = [allVarNames, tableCell{i}.Properties.VariableNames];
                    end
                end
                allVarNames = unique(allVarNames, 'stable');

                for i = 1:numel(tableCell)
                    tbl = tableCell{i};
                    if isempty(tbl) || ~istable(tbl)
                        continue
                    end

                    existingVars = tbl.Properties.VariableNames;
                    missingVars = setdiff(allVarNames, existingVars, 'stable');

                    for j = 1:numel(missingVars)
                        varName = missingVars{j};
                        nRows = height(tbl);

                        varType = 'double';
                        for k = 1:numel(tableCell)
                            if ~isempty(tableCell{k}) && istable(tableCell{k}) && ...
                                    ismember(varName, tableCell{k}.Properties.VariableNames)
                                refCol = tableCell{k}.(varName);
                                varType = class(refCol);
                                break;
                            end
                        end

                        switch varType
                            case {'double', 'single'}
                                tbl.(varName) = nan(nRows, 1);
                            case 'cell'
                                tbl.(varName) = cell(nRows, 1);
                            case {'char', 'string'}
                                tbl.(varName) = repmat({''}, nRows, 1);
                            case 'datetime'
                                tbl.(varName) = NaT(nRows, 1);
                            case 'logical'
                                tbl.(varName) = false(nRows, 1);
                            otherwise
                                tbl.(varName) = cell(nRows, 1);
                        end
                    end

                    tbl = tbl(:, allVarNames);
                    tableCell{i} = tbl;
                end

                harmonizedTables = tableCell;
            end

            function filteredCompTable = applyLODFiltering(compTable, lodTable, compSuffix, lodSuffix)
                filteredCompTable = compTable;

                if isempty(compTable) || isempty(lodTable) || width(compTable) == 0 || width(lodTable) == 0
                    return
                end

                compCols = compTable.Properties.VariableNames;
                compCols = compCols(contains(compCols, compSuffix));

                lodCols = lodTable.Properties.VariableNames;
                lodCols = lodCols(contains(lodCols, lodSuffix));

                for l = 1:numel(compCols)
                    elementName = strrep(compCols{l}, compSuffix, '');
                    lodColName = [elementName, lodSuffix];

                    if ismember(lodColName, lodCols)
                        comp_array = compTable.(compCols{l});
                        lod_array = lodTable.(lodColName);

                        if isnumeric(comp_array) && isnumeric(lod_array)
                            below_lod = comp_array < lod_array;
                            display_values = num2cell(comp_array);
                            display_values(below_lod) = arrayfun(@(x) ['<' num2str(x)], ...
                                lod_array(below_lod), 'UniformOutput', false);
                            filteredCompTable.(compCols{l}) = display_values;
                        end
                    end
                end
            end

        end


        function Base_Table = convert_majors_to_oxides_base_table(app, Base_Table)
            % Extract oxide settings from the first available session in app.base
            oxides_found = false;
            for i = 1:numel(app.base)
                for j = 1:numel(app.base(i).project)
                    if isfield(app.base(i).project(j), 'session') && isfield(app.base(i).project(j).session, 'oxides') ...
                            && ~isempty(app.base(i).project(j).session.oxides)
                        session_oxides = app.base(i).project(j).session.oxides;
                        oxides_found = true;
                        break;
                    end
                end
                if oxides_found; break; end
            end

            if ~oxides_found
                return; % Exit safely if no conversion settings exist
            end

            element_names = session_oxides.element_names;
            oxide_names = session_oxides.oxide_names;
            considered_major = session_oxides.considered_major;
            oxide_factors = session_oxides.conversion_factors;

            var_names = Base_Table.Properties.VariableNames;
            new_var_names = var_names;

            % Loop through each column in the Base_Table
            for colIdx = 1:length(var_names)
                colName = var_names{colIdx};

                % Look for composition or LOD columns
                if contains(colName, '_ugg') || contains(colName, '_lod')
                    % Extract the base element (e.g. 'Fe' from 'Fe57_mat_ugg' or 'Fe_mat_ugg')
                    base_element = regexp(colName, '^[A-Za-z]+', 'match', 'once');

                    if ~isempty(base_element)
                        element_idx = find(strcmp(element_names, base_element));

                        % If it's a major element to be converted into oxides...
                        if ~isempty(element_idx) && strcmp(considered_major{element_idx}, 'Yes') && isKey(oxide_factors, base_element)
                            factor = oxide_factors(base_element);
                            oxide_name = oxide_names{element_idx};

                            colData = Base_Table.(colName);

                            % Process the column data (accommodating strings like '<0.012' from the lod filter)
                            if iscell(colData)
                                for rowIdx = 1:numel(colData)
                                    val = colData{rowIdx};
                                    if isnumeric(val)
                                        colData{rowIdx} = (val * factor) / 10000;
                                    elseif ischar(val) || isstring(val)
                                        valStr = char(val);
                                        if startsWith(valStr, '<')
                                            numVal = str2double(extractAfter(valStr, 1));
                                            if ~isnan(numVal)
                                                colData{rowIdx} = ['<', num2str((numVal * factor) / 10000)];
                                            end
                                        else
                                            numVal = str2double(valStr);
                                            if ~isnan(numVal)
                                                colData{rowIdx} = num2str((numVal * factor) / 10000);
                                            end
                                        end
                                    end
                                end
                            elseif isnumeric(colData)
                                colData = (colData .* factor) ./ 10000; % Apply factors and convert to wt%
                            end

                            % Reassign column data to the table
                            Base_Table.(colName) = colData;

                            % Build the new oxide header (preserves LOD/Host/Incl suffixes)
                            isotope_part = regexp(colName, '\d+', 'match', 'once');
                            has_isotope = ~isempty(isotope_part);

                            % Remove base element and isotope (if present) to keep suffixes intact
                            element_part_length = length(base_element) + (has_isotope * length(isotope_part));
                            suffix_part = extractAfter(colName, element_part_length);

                            if has_isotope
                                new_var_names{colIdx} = [oxide_name, '_', isotope_part, suffix_part];
                            else
                                new_var_names{colIdx} = [oxide_name, suffix_part];
                            end
                        end
                    end
                end
            end

            % Assign the new oxides variable names to the Base_Table
            Base_Table.Properties.VariableNames = new_var_names;
        end

        function Base_Table = exclude_CALs(app, Base_Table)

            typeVals = strtrim(string(Base_Table.Type));
            isCal = strcmpi(typeVals, "CAL");
            Base_Table = Base_Table(~isCal, :);

        end


% with assignment but without proper mass from name removal
%         function Base_Table = build_assigned_table_from_base(app)
%             % --- Get checked nodes from SpotMetaTree ---
%             checkedMetaNodes = app.SpotMetaTree.CheckedNodes;
%             if isempty(checkedMetaNodes)
%                 Base_Table = table();
%                 return
%             end
%             checkedMetaLabels = string({checkedMetaNodes.NodeData}); % assumes NodeData is string or char
% 
%             % Helper to check if a label is checked
%             isChecked = @(label) any(checkedMetaLabels == label);
% 
%             % --- Always needed: Metadata ---
%             allProjects = {};
%             allSessions = {};
%             allComments = {};
%             allAnalysts = {};
%             allProcessors = {};
%             allSpotnames = {};
%             alldates = {};
%             dynamicMetaData = struct();
% 
%             allTypes = {};
%             allRefMaterials = {};
%             allIntTimePoints = {};
%             allusedBracket_id = {};
% 
%             % Host-Inclusion-unmixing
%             allUnmixingVia = {};
%             allMatrixOnlyTracer = {};
%             allMatrixFromSpot = {};
% 
%             % Sensitivities
%             allSensitivitiesRows = {};
%             % Matrix/Host mass fractions
%             allmatrixcompRows = {};
%             % Inclusion mass fractions
%             allinclcompRows = {};
%             % Matrix/Host limits of detection
%             allmatrixlodRows = {};
%             % Inclusion limits of detection
%             allincllodRows = {};
%             % Mix mass fractions
%             allmixcompRows = {};
% 
%             % cps
%             allbkgdCpsRows_mat = {};
%             allbkgdCpsRows_incl = {};
%             allbkgdCpsRows_mix = {};
% 
%             % LA parameters
%             allLaserParamTables = {};
%             % ICP-MS parameters
%             allICPMSParamTables = {};
% 
%             % Get checked nodes from the project/session tree
%             checkedNodes = app.ProjectSessionTree.CheckedNodes;
%             checkedIndices = [];
%             for n = 1:numel(checkedNodes)
%                 nodeData = checkedNodes(n).NodeData;
%                 if ~isempty(nodeData) && numel(nodeData) == 2 && nodeData(2) > 0
%                     checkedIndices = [checkedIndices; nodeData];
%                 end
%             end
% 
%             % --- Pre-scan selected sessions to find all dynamic metadata fields ---
%             for i = 1:numel(app.base)
%                 for j = 1:numel(app.base(i).project)
%                     if isempty(checkedIndices) || ~ismember([i, j], checkedIndices, 'rows')
%                         continue
%                     end
%                     if isfield(app.base(i).project(j), 'session')
%                         session = app.base(i).project(j).session;
%                     else
%                         continue
%                     end
% 
%                     if ~isfield(session, 'spot') || isempty(session.spot)
%                         continue
%                     end
%                     nSpots = numel(session.spot.name);
% 
%                     if isfield(session, 'meta') && isstruct(session.meta)
%                         metaFields = fieldnames(session.meta);
%                         for f = 1:numel(metaFields)
%                             fieldName = metaFields{f};
%                             if iscell(session.meta.(fieldName)) && size(session.meta.(fieldName), 1) == nSpots
%                                 if ~isfield(dynamicMetaData, fieldName)
%                                     dynamicMetaData.(fieldName) = {};
%                                 end
%                             end
%                         end
%                     end
%                 end
%             end
% 
%             dynamicMetaFields = fieldnames(dynamicMetaData);
%             missingAssignmentSessions = {};
% 
%             for i = 1:numel(app.base)
%                 projectName = app.base(i).project_name;
% 
%                 for j = 1:numel(app.base(i).project)
%                     if isempty(checkedIndices) || ~ismember([i, j], checkedIndices, 'rows')
%                         continue
%                     end
% 
%                     if isfield(app.base(i).project(j), 'session')
%                         session = app.base(i).project(j).session;
%                     else
%                         continue
%                     end
% 
%                     % Skip if any module status is 0
%                     if isfield(session, 'config') && isfield(session.config, 'module_status')
%                         if any(structfun(@(x) x == 0, session.config.module_status))
%                             continue
%                         end
%                     end
% 
%                     % Session meta
%                     if isfield(session, 'session_name') && ~isempty(session.session_name)
%                         sessionName = char(session.session_name);
%                     else
%                         sessionName = '';
%                     end
% 
%                     if isfield(session, 'meta') && isfield(session.meta, 'date') && ~isempty(session.meta.date)
%                         sessiondate = session.meta.date;
%                     else
%                         sessiondate = '';
%                     end
% 
%                     if isfield(session, 'meta') && isfield(session.meta, 'session_comment') && ~isempty(session.meta.session_comment)
%                         sessionComment = char(session.meta.session_comment);
%                     else
%                         sessionComment = '';
%                     end
% 
%                     if isfield(session, 'meta') && isfield(session.meta, 'analyst') && ~isempty(session.meta.analyst)
%                         analyst = char(session.meta.analyst);
%                     else
%                         analyst = '';
%                     end
% 
%                     if isfield(session, 'meta') && isfield(session.meta, 'processed_by') && ~isempty(session.meta.processed_by)
%                         processed_by = char(session.meta.processed_by);
%                     else
%                         processed_by = '';
%                     end
% 
%                     % Spot block
%                     if ~isfield(session, 'spot') || isempty(session.spot)
%                         continue
%                     end
% 
%                     spotnames = session.spot.name;
%                     nSpots = numel(spotnames);
% 
%                     % Require analyte_calibrator_assignment in selected sessions
%                     if ~isfield(session.spot, 'analyte_calibrator_assignment') || ...
%                             isempty(session.spot.analyte_calibrator_assignment)
% 
%                         sessionLabel = sessionName;
% 
%                         missingAssignmentSessions{end+1,1} = [char(projectName) ' / ' sessionLabel];
%                         continue
%                     end
% 
%                     assignments = session.spot.analyte_calibrator_assignment;
%                     [assignmentMap, assignedCalNames] = buildAnalyteCalMap(assignments);
% 
%                     % --- Extract dynamic metadata for current session ---
%                     sessionDynamicData = struct();
%                     for f = 1:numel(dynamicMetaFields)
%                         fieldName = dynamicMetaFields{f};
%                         if isfield(session, 'meta') && isfield(session.meta, fieldName) && ~isempty(session.meta.(fieldName))
%                             sessionDynamicData.(fieldName) = session.meta.(fieldName)(:);
%                         else
%                             sessionDynamicData.(fieldName) = repmat({''}, nSpots, 1);
%                         end
%                     end
% 
%                     % Type and CALs (backward compatibility, local only)
%                     if isfield(session.spot, 'type') && ~isempty(session.spot.type)
%                         type = string(session.spot.type(:));
%                     else
%                         type = strings(nSpots, 1);
%                     end
%                     type(type == "PRM") = "CAL";
%                     type(type == "SRM") = "QCRM";
% 
%                     if isfield(session.spot, 'referencematerial') && ~isempty(session.spot.referencematerial)
%                         referencematerial = string(session.spot.referencematerial(:));
%                     else
%                         referencematerial = strings(nSpots, 1);
%                     end
% 
%                     % Session-local CAL list
%                     cal_indices = (type == "CAL");
%                     if any(cal_indices)
%                         cal_materials = referencematerial(cal_indices);
%                         validCALs = unique(cal_materials); % no 'stable' by design
%                         validCALs = validCALs(~strcmp(validCALs, "None"));
%                         validCALs = validCALs(~strcmp(validCALs, "Select Reference Material"));
%                     else
%                         validCALs = string.empty(0,1);
%                     end
% 
%                     % Bracket IDs
%                     if isfield(session.spot, 'bracket_id') && ~isempty(session.spot.bracket_id)
%                         bracket_id = session.spot.bracket_id(:);
%                         if ~isnumeric(bracket_id)
%                             bracket_id = zeros(nSpots,1);
%                         end
%                         uniqueBracketIds = unique(bracket_id);
%                         uniqueBracketIds = uniqueBracketIds(uniqueBracketIds ~= 0);
%                     else
%                         uniqueBracketIds = [];
%                     end
% 
%                     % Integer time points
%                     if isfield(session.spot, 'integer_time_points') && ~isempty(session.spot.integer_time_points)
%                         integer_time_points = session.spot.integer_time_points(:);
%                         if ~isnumeric(integer_time_points)
%                             integer_time_points = NaN(nSpots,1);
%                         end
%                     else
%                         integer_time_points = NaN(nSpots,1);
%                     end
% 
%                     % Analyte names
%                     if isfield(session.spot, 'analytes') && ~isempty(session.spot.analytes)
%                         analyteNames = strtrim(string(session.spot.analytes(:)));
%                     else
%                         analyteNames = strings(0,1);
%                     end
% 
%                     % If no CALs or no brackets, skip session
%                     if isempty(validCALs) || isempty(uniqueBracketIds)
%                         continue
%                     end
% 
%                     % If all assigned analytes point to one CAL, metadata can be resolved.
%                     % Otherwise these CAL-dependent metadata columns are ambiguous and filled with defaults.
%                     singleAssignedCalIdx = [];
%                     if numel(assignedCalNames) == 1
%                         onlyCal = char(assignedCalNames(1));
%                         singleAssignedCalIdx = find(strcmp(validCALs, onlyCal), 1);
%                     end
% 
%                     % --- Only process blocks if checked in SpotMetaTree ---
% 
%                     % LA parameters
%                     laserParamTable = table();
%                     if isChecked("LA parameters")
%                         if isfield(session, 'meta') && isfield(session.meta, 'laser_parameters')
%                             laserParamStruct = session.meta.laser_parameters;
%                             laserParamTable = struct_to_table(laserParamStruct);
%                         else
%                             laserParamTable = table();
%                         end
%                     end
% 
%                     % ICP-MS parameters
%                     ICPMSParamTable = table();
%                     if isChecked("ICP-MS parameters")
%                         if isfield(session, 'meta') && isfield(session.meta, 'icp_ms_parameters')
%                             ICPMSParamStruct = session.meta.icp_ms_parameters;
%                             ICPMSParamTable = struct_to_table(ICPMSParamStruct);
%                         else
%                             ICPMSParamTable = table();
%                         end
%                     end
% 
%                     % Sensitivities
%                     if isChecked("Sensitivities")
%                         if isfield(session.spot, 'cal_sensitivities')
%                             cal_sensitivities = session.spot.cal_sensitivities;
%                         elseif isfield(session.spot, 'prm_sensitivities')
%                             cal_sensitivities = session.spot.prm_sensitivities;
%                         else
%                             cal_sensitivities = table();
%                         end
%                         sensitivities = getNestedTable(session.spot, 'sensitivities', uniqueBracketIds, validCALs, nSpots, analyteNames);
% 
%                         % Apply cal_sensitivities patches to all CAL tables (same behavior as before)
%                         if ~isempty(cal_sensitivities) && istable(cal_sensitivities)
%                             for b = 1:numel(uniqueBracketIds)
%                                 for p = 1:numel(validCALs)
%                                     sens_tbl = ensureAnalyteTable(sensitivities{b,p}, analyteNames, nSpots);
%                                     common_cols = intersect(sens_tbl.Properties.VariableNames, cal_sensitivities.Properties.VariableNames);
%                                     if ~isempty(common_cols)
%                                         nRowsCommon = min(height(sens_tbl), height(cal_sensitivities));
%                                         if nRowsCommon > 0
%                                             nonzero_rows = any(table2array(cal_sensitivities(1:nRowsCommon, :)) ~= 0, 2);
%                                             idxRows = find(nonzero_rows);
%                                             for cc = 1:numel(common_cols)
%                                                 colName = common_cols{cc};
%                                                 sens_tbl{idxRows, colName} = cal_sensitivities{idxRows, colName};
%                                             end
%                                         end
%                                     end
%                                     sensitivities{b,p} = sens_tbl;
%                                 end
%                             end
%                         end
%                     end
% 
%                     % Matrix/Host mass fractions
%                     if isChecked("Matrix/Host mass fractions")
%                         matrix_composition = getNestedTable(session.spot, 'matrix_composition', uniqueBracketIds, validCALs, nSpots, analyteNames);
%                     end
% 
%                     % Matrix/Host limits of detection
%                     if isChecked("Matrix/Host limits of detection") || isChecked("Matrix/Host mass fractions")
%                         matrix_limits_of_detection = getNestedTable(session.spot, 'matrix_limits_of_detection', uniqueBracketIds, validCALs, nSpots, analyteNames);
%                     end
% 
%                     % Inclusion blocks
%                     if isChecked("Inclusion mass fractions")
%                         inclusion_composition = getNestedTable(session.spot, 'inclusion_composition', uniqueBracketIds, validCALs, nSpots, analyteNames);
%                     end
%                     if isChecked("Inclusion limits of detection") || isChecked("Inclusion mass fractions") || isChecked("Mix mass fractions")
%                         inclusion_limits_of_detection = getNestedTable(session.spot, 'inclusion_limits_of_detection', uniqueBracketIds, validCALs, nSpots, analyteNames);
%                     end
%                     if isChecked("Mix mass fractions")
%                         mix_composition = getNestedTable(session.spot, 'mix_composition', uniqueBracketIds, validCALs, nSpots, analyteNames);
%                     end
% 
%                     % Mixing ratios / unmixing metadata
%                     if isChecked("Host-Inclusion-unmixing")
% 
%                         if isfield(session.spot, 'unmixing_via') && ~isempty(session.spot.unmixing_via)
%                             unmixing_via = string(session.spot.unmixing_via(:));
%                         else
%                             unmixing_via = repmat("", nSpots, 1);
%                         end
%                         if isfield(session.spot, 'matrix_only_tracer') && ~isempty(session.spot.matrix_only_tracer)
%                             matrix_only_tracer = string(session.spot.matrix_only_tracer(:));
%                         else
%                             matrix_only_tracer = repmat("", nSpots, 1);
%                         end
%                         if isfield(session.spot, 'matrix_from_spot') && ~isempty(session.spot.matrix_from_spot)
%                             matrix_from_spot = string(session.spot.matrix_from_spot(:));
%                         else
%                             matrix_from_spot = repmat("", nSpots, 1);
%                         end
%                     end
% 
%                     % cps
%                     if isfield(session.spot, 'bkgd_corrected_cps')
%                         bkgdCps_mat = session.spot.bkgd_corrected_cps;
%                     else
%                         bkgdCps_mat = table('Size', [nSpots, numel(analyteNames)], ...
%                             'VariableTypes', repmat("double", 1, numel(analyteNames)), ...
%                             'VariableNames', cellstr(analyteNames));
%                     end
% 
%                     if isChecked("Inclusion counts-per-sweep")
%                         if isfield(session.spot, 'bkgd_corrected_cps_incl')
%                             bkgdCps_incl = session.spot.bkgd_corrected_cps_incl;
%                         else
%                             bkgdCps_incl = array2table(zeros(nSpots, numel(analyteNames)), ...
%                                 'VariableNames', cellstr(analyteNames));
%                         end
%                     end
% 
%                     if isChecked("Mix counts-per-sweep")
%                         if isfield(session.spot, 'bkgd_corrected_cps_mix')
%                             bkgdCps_mix = session.spot.bkgd_corrected_cps_mix;
%                         else
%                             bkgdCps_mix = array2table(zeros(nSpots, numel(analyteNames)), ...
%                                 'VariableNames', cellstr(analyteNames));
%                         end
%                     end
% 
%                     % --- Core logic ---
%                     % Rows are duplicated only by bracket and spot.
%                     % CAL selection for analyte values is analyte-wise from analyte_calibrator_assignment.
%                     for b = 1:numel(uniqueBracketIds)
%                         % Metadata rows (nSpots per bracket)
%                         for k = 1:nSpots
%                             allProjects{end+1,1} = projectName;
%                             allSessions{end+1,1} = sessionName;
%                             allComments{end+1,1} = sessionComment;
%                             allAnalysts{end+1,1} = analyst;
%                             allProcessors{end+1,1} = processed_by;
%                             alldates{end+1,1} = sessiondate;
%                             allSpotnames{end+1,1} = spotnames{k};
% 
%                             for f = 1:numel(dynamicMetaFields)
%                                 fieldName = dynamicMetaFields{f};
%                                 currentColumn = sessionDynamicData.(fieldName);
%                                 if numel(currentColumn) >= k
%                                     dynamicMetaData.(fieldName){end+1,1} = currentColumn{k};
%                                 else
%                                     dynamicMetaData.(fieldName){end+1,1} = '';
%                                 end
%                             end
% 
%                             if numel(type) >= k && ~ismissing(type(k))
%                                 allTypes{end+1,1} = type(k);
%                             else
%                                 allTypes{end+1,1} = "";
%                             end
% 
%                             if numel(referencematerial) >= k && ~ismissing(referencematerial(k))
%                                 allRefMaterials{end+1,1} = referencematerial(k);
%                             else
%                                 allRefMaterials{end+1,1} = "";
%                             end
% 
%                             allIntTimePoints{end+1,1} = integer_time_points(k);
%                             allusedBracket_id{end+1,1} = uniqueBracketIds(b);
% 
%                             if isChecked("Host-Inclusion-unmixing")
%                                 if numel(unmixing_via) >= k
%                                     allUnmixingVia{end+1,1} = unmixing_via(k);
%                                 else
%                                     allUnmixingVia{end+1,1} = "";
%                                 end
%                                 if numel(matrix_only_tracer) >= k
%                                     allMatrixOnlyTracer{end+1,1} = matrix_only_tracer(k);
%                                 else
%                                     allMatrixOnlyTracer{end+1,1} = "";
%                                 end
%                                 if numel(matrix_from_spot) >= k
%                                     allMatrixFromSpot{end+1,1} = matrix_from_spot(k);
%                                 else
%                                     allMatrixFromSpot{end+1,1} = "";
%                                 end
%                             end
%                         end
% 
%                         % Numeric blocks per bracket using analyte-level CAL assignment
%                         if isChecked("Sensitivities")
%                             sensTblAssigned = buildAssignedAnalyteTable(sensitivities, b, assignmentMap, validCALs, analyteNames, nSpots);
%                             allSensitivitiesRows{end+1,1} = ensureAnalyteTable(sensTblAssigned, analyteNames, nSpots);
%                         end
% 
%                         if isChecked("Matrix/Host mass fractions")
%                             matrixCompAssigned = buildAssignedAnalyteTable(matrix_composition, b, assignmentMap, validCALs, analyteNames, nSpots);
%                             allmatrixcompRows{end+1,1} = ensureAnalyteTable(matrixCompAssigned, analyteNames, nSpots);
%                         end
% 
%                         if isChecked("Matrix/Host limits of detection") || isChecked("Matrix/Host mass fractions")
%                             matrixLodAssigned = buildAssignedAnalyteTable(matrix_limits_of_detection, b, assignmentMap, validCALs, analyteNames, nSpots);
%                             allmatrixlodRows{end+1,1} = ensureAnalyteTable(matrixLodAssigned, analyteNames, nSpots);
%                         end
% 
%                         if isChecked("Inclusion mass fractions")
%                             inclusionCompAssigned = buildAssignedAnalyteTable(inclusion_composition, b, assignmentMap, validCALs, analyteNames, nSpots);
%                             allinclcompRows{end+1,1} = ensureAnalyteTable(inclusionCompAssigned, analyteNames, nSpots);
%                         end
% 
%                         if isChecked("Inclusion limits of detection") || isChecked("Inclusion mass fractions") || isChecked("Mix mass fractions")
%                             inclusionLodAssigned = buildAssignedAnalyteTable(inclusion_limits_of_detection, b, assignmentMap, validCALs, analyteNames, nSpots);
%                             allincllodRows{end+1,1} = ensureAnalyteTable(inclusionLodAssigned, analyteNames, nSpots);
%                         end
% 
%                         if isChecked("Mix mass fractions")
%                             mixCompAssigned = buildAssignedAnalyteTable(mix_composition, b, assignmentMap, validCALs, analyteNames, nSpots);
%                             allmixcompRows{end+1,1} = ensureAnalyteTable(mixCompAssigned, analyteNames, nSpots);
%                         end
% 
%                         % cps
%                         allbkgdCpsRows_mat{end+1,1} = ensureAnalyteTable(bkgdCps_mat, analyteNames, nSpots);
% 
%                         if isChecked("Inclusion counts-per-sweep")
%                             allbkgdCpsRows_incl{end+1,1} = ensureAnalyteTable(bkgdCps_incl, analyteNames, nSpots);
%                         end
%                         if isChecked("Mix counts-per-sweep")
%                             allbkgdCpsRows_mix{end+1,1} = ensureAnalyteTable(bkgdCps_mix, analyteNames, nSpots);
%                         end
% 
%                         % LA/ICP parameter tables (one per bracket)
%                         if isChecked("LA parameters")
%                             allLaserParamTables{end+1,1} = laserParamTable;
%                         end
%                         if isChecked("ICP-MS parameters")
%                             allICPMSParamTables{end+1,1} = ICPMSParamTable;
%                         end
%                     end
%                 end
%             end
% 
%             if ~isempty(missingAssignmentSessions)
%                 missingAssignmentSessions = unique(missingAssignmentSessions);
% 
%                 if numel(missingAssignmentSessions) == 1
%                     msg = sprintf(['The analyte_calibrator_assignment is missing in session:\n\n%s\n\n' ...
%                         'This session was skipped.'], missingAssignmentSessions{1});
%                 else
%                     msg = sprintf(['The analyte_calibrator_assignment is missing in these sessions:\n\n%s\n\n' ...
%                         'These sessions were skipped.'], strjoin(missingAssignmentSessions, newline));
%                 end
% 
%                 uialert(app.TableBuilderUIFigure, msg, ...
%                     'Missing analyte-calibrator assignment', ...
%                     'Icon', 'warning');
%             end
% 
%             % --- Build Metadata Table (always included) ---
%             Metadata_Table = table(allProjects, allSessions, allComments, allAnalysts, allProcessors, alldates, allSpotnames, ...
%                 allTypes, allRefMaterials, allIntTimePoints, allusedBracket_id, ...
%                 'VariableNames', {'Project', 'Session', 'Comment','Analyst', 'Processed by', 'Date', 'Spotname', ...
%                 'Type', 'Reference_Material', 'Integer_Time_Points', 'used_bracket_id'});
% 
%             % Add dynamic metadata columns
%             for f = 1:numel(dynamicMetaFields)
%                 fieldName = dynamicMetaFields{f};
%                 Metadata_Table.(fieldName) = dynamicMetaData.(fieldName);
%             end
% 
%             if isChecked("Host-Inclusion-unmixing")
%                 Metadata_Table.Unmixing_Via = allUnmixingVia;
%                 Metadata_Table.Matrix_Only_Tracer = allMatrixOnlyTracer;
%                 Metadata_Table.Matrix_From_Spot = allMatrixFromSpot;
%             end
% 
%             % --- Build selected numeric/parameter blocks ---
%             tableBlocks = {Metadata_Table};
% 
%             if isChecked("Matrix/Host mass fractions")
%                 allmatrixlodTable = buildNumericBlock(allmatrixlodRows, 'Matrix_LOD');
%                 allmatrixlodTable = renameAnalyteColumns(allmatrixlodTable, '_mat_lod');
%                 if ismember('Data_Source', allmatrixlodTable.Properties.VariableNames)
%                     allmatrixlodTable.Data_Source = [];
%                 end
% 
%                 allmatrixcompTable = buildNumericBlock(allmatrixcompRows, 'Matrix_Composition');
%                 allmatrixcompTable = renameAnalyteColumns(allmatrixcompTable, '_mat_ugg');
%                 if ismember('Data_Source', allmatrixcompTable.Properties.VariableNames)
%                     allmatrixcompTable.Data_Source = [];
%                 end
% 
%                 allmatrixcompTable = applyLODFiltering(allmatrixcompTable, allmatrixlodTable, '_mat_ugg', '_mat_lod');
%                 tableBlocks{end+1} = allmatrixcompTable;
%             end
% 
%             if isChecked("Inclusion mass fractions")
%                 allinclusionlodTable = buildNumericBlock(allincllodRows, 'Inclusion_LOD');
%                 allinclusionlodTable = renameAnalyteColumns(allinclusionlodTable, '_incl_lod');
%                 if ismember('Data_Source', allinclusionlodTable.Properties.VariableNames)
%                     allinclusionlodTable.Data_Source = [];
%                 end
% 
%                 allinclusioncompTable = buildNumericBlock(allinclcompRows, 'Inclusion_Composition');
%                 allinclusioncompTable = renameAnalyteColumns(allinclusioncompTable, '_incl_ugg');
%                 if ismember('Data_Source', allinclusioncompTable.Properties.VariableNames)
%                     allinclusioncompTable.Data_Source = [];
%                 end
% 
%                 allinclusioncompTable = applyLODFiltering(allinclusioncompTable, allinclusionlodTable, '_incl_ugg', '_incl_lod');
%                 tableBlocks{end+1} = allinclusioncompTable;
%             end
% 
%             if isChecked("Mix mass fractions")
%                 allinclusionlodTable = buildNumericBlock(allincllodRows, 'Inclusion_LOD');
%                 allinclusionlodTable = renameAnalyteColumns(allinclusionlodTable, '_incl_lod');
%                 if ismember('Data_Source', allinclusionlodTable.Properties.VariableNames)
%                     allinclusionlodTable.Data_Source = [];
%                 end
% 
%                 allmixcompTable = buildNumericBlock(allmixcompRows, 'Mix_Composition');
%                 allmixcompTable = renameAnalyteColumns(allmixcompTable, '_mix_ugg');
%                 if ismember('Data_Source', allmixcompTable.Properties.VariableNames)
%                     allmixcompTable.Data_Source = [];
%                 end
% 
%                 allmixcompTable = applyLODFiltering(allmixcompTable, allinclusionlodTable, '_mix_ugg', '_incl_lod');
%                 tableBlocks{end+1} = allmixcompTable;
%             end
% 
%             if isChecked("Matrix/Host limits of detection")
%                 allmatrixlodTable = buildNumericBlock(allmatrixlodRows, 'Matrix_LOD');
%                 allmatrixlodTable = renameAnalyteColumns(allmatrixlodTable, '_mat_lod');
%                 if ismember('Data_Source', allmatrixlodTable.Properties.VariableNames)
%                     allmatrixlodTable.Data_Source = [];
%                 end
%                 tableBlocks{end+1} = allmatrixlodTable;
%             end
% 
%             if isChecked("Inclusion limits of detection")
%                 allinclusionlodTable = buildNumericBlock(allincllodRows, 'Inclusion_LOD');
%                 allinclusionlodTable = renameAnalyteColumns(allinclusionlodTable, '_incl_lod');
%                 if ismember('Data_Source', allinclusionlodTable.Properties.VariableNames)
%                     allinclusionlodTable.Data_Source = [];
%                 end
%                 tableBlocks{end+1} = allinclusionlodTable;
%             end
% 
%             if isChecked("Matrix/Host counts-per-sweep")
%                 allbkgdCpsTable_mat = buildNumericBlock(allbkgdCpsRows_mat, 'Bkgd_Corrected_CPS_mat');
%                 allbkgdCpsTable_mat = renameAnalyteColumns(allbkgdCpsTable_mat, '_mat_cps');
%                 if ismember('Data_Source', allbkgdCpsTable_mat.Properties.VariableNames)
%                     allbkgdCpsTable_mat.Data_Source = [];
%                 end
%                 tableBlocks{end+1} = allbkgdCpsTable_mat;
%             end
% 
%             if isChecked("Inclusion counts-per-sweep")
%                 allbkgdCpsTable_incl = buildNumericBlock(allbkgdCpsRows_incl, 'Bkgd_Corrected_CPS_incl');
%                 allbkgdCpsTable_incl = renameAnalyteColumns(allbkgdCpsTable_incl, '_incl_cps');
%                 if ismember('Data_Source', allbkgdCpsTable_incl.Properties.VariableNames)
%                     allbkgdCpsTable_incl.Data_Source = [];
%                 end
%                 tableBlocks{end+1} = allbkgdCpsTable_incl;
%             end
% 
%             if isChecked("Mix counts-per-sweep")
%                 allbkgdCpsTable_mix = buildNumericBlock(allbkgdCpsRows_mix, 'Bkgd_Corrected_CPS_mix');
%                 allbkgdCpsTable_mix = renameAnalyteColumns(allbkgdCpsTable_mix, '_mix_cps');
%                 if ismember('Data_Source', allbkgdCpsTable_mix.Properties.VariableNames)
%                     allbkgdCpsTable_mix.Data_Source = [];
%                 end
%                 tableBlocks{end+1} = allbkgdCpsTable_mix;
%             end
% 
%             if isChecked("Sensitivities")
%                 allsensTable = buildNumericBlock(allSensitivitiesRows, 'Sensitivities');
%                 allsensTable = renameAnalyteColumns(allsensTable, '_sens');
%                 if ismember('Data_Source', allsensTable.Properties.VariableNames)
%                     allsensTable.Data_Source = [];
%                 end
%                 tableBlocks{end+1} = allsensTable;
%             end
% 
%             if isChecked("LA parameters")
%                 for i = 1:numel(allLaserParamTables)
%                     if isempty(allLaserParamTables{i}) || height(allLaserParamTables{i}) == 0
%                         nRows = height(allbkgdCpsRows_mat{i});
%                         for j = 1:numel(allLaserParamTables)
%                             if ~isempty(allLaserParamTables{j}) && height(allLaserParamTables{j}) > 0
%                                 refTable = allLaserParamTables{j};
%                                 varNames = refTable.Properties.VariableNames;
%                                 varTypes = varfun(@class, refTable, 'OutputFormat', 'cell');
%                                 emptyData = cell(1, numel(varNames));
%                                 for k = 1:numel(varNames)
%                                     switch varTypes{k}
%                                         case {'double', 'single'}
%                                             emptyData{k} = zeros(nRows,1);
%                                         case {'cell'}
%                                             emptyData{k} = cell(nRows,1);
%                                         case {'char', 'string'}
%                                             emptyData{k} = repmat({''}, nRows,1);
%                                         case {'datetime'}
%                                             emptyData{k} = NaT(nRows,1);
%                                         case {'logical'}
%                                             emptyData{k} = false(nRows,1);
%                                         otherwise
%                                             emptyData{k} = cell(nRows,1);
%                                     end
%                                 end
%                                 allLaserParamTables{i} = table(emptyData{:}, 'VariableNames', varNames);
%                                 break;
%                             end
%                         end
%                     end
%                 end
% 
%                 allLaserParamTables = harmonizeTableColumns(allLaserParamTables);
%                 allLaserParamTables = vertcat(allLaserParamTables{:});
%                 tableBlocks{end+1} = allLaserParamTables;
%             end
% 
%             if isChecked("ICP-MS parameters")
%                 for i = 1:numel(allICPMSParamTables)
%                     nRows = height(allbkgdCpsRows_mat{i});
%                     tbl = allICPMSParamTables{i};
% 
%                     if isempty(tbl) || height(tbl) == 0
%                         for j = 1:numel(allICPMSParamTables)
%                             if ~isempty(allICPMSParamTables{j}) && height(allICPMSParamTables{j}) > 0
%                                 refTable = allICPMSParamTables{j};
%                                 varNames = refTable.Properties.VariableNames;
%                                 emptyData = cell(1, numel(varNames));
%                                 for k = 1:numel(varNames)
%                                     refCol = refTable.(varNames{k});
%                                     refType = class(refCol);
%                                     if strcmp(refType, 'cell')
%                                         firstNonEmpty = find(~cellfun(@isempty, refCol), 1);
%                                         if isempty(firstNonEmpty)
%                                             emptyData{k} = repmat({''}, nRows, 1);
%                                         else
%                                             content = refCol{firstNonEmpty};
%                                             if ischar(content) || isstring(content)
%                                                 emptyData{k} = repmat({''}, nRows, 1);
%                                             elseif isnumeric(content)
%                                                 emptyData{k} = num2cell(zeros(nRows, 1));
%                                             else
%                                                 emptyData{k} = cell(nRows, 1);
%                                             end
%                                         end
%                                     elseif strcmp(refType, 'double') || strcmp(refType, 'single')
%                                         emptyData{k} = zeros(nRows, 1);
%                                     elseif strcmp(refType, 'char') || strcmp(refType, 'string')
%                                         emptyData{k} = repmat({''}, nRows, 1);
%                                     elseif strcmp(refType, 'datetime')
%                                         emptyData{k} = NaT(nRows, 1);
%                                     elseif strcmp(refType, 'logical')
%                                         emptyData{k} = false(nRows, 1);
%                                     else
%                                         emptyData{k} = cell(nRows, 1);
%                                     end
%                                 end
%                                 tbl = table(emptyData{:}, 'VariableNames', varNames);
%                                 break;
%                             end
%                         end
%                     elseif height(tbl) == 1 && nRows > 1
%                         tbl = tbl(ones(nRows,1), :);
%                     end
% 
%                     allICPMSParamTables{i} = tbl;
%                 end
% 
%                 allICPMSParamTables = harmonizeTableColumns(allICPMSParamTables);
%                 allICPMSParamTables = vertcat(allICPMSParamTables{:});
%                 tableBlocks{end+1} = allICPMSParamTables;
%             end
% 
%             % --- Concatenate selected blocks ---
%             Base_Table = [tableBlocks{:}];
% 
%             % --- Normalize textual columns and replace numeric zeros with NaN ---
%             if ~isempty(Base_Table)
%                 if ismember('Type', Base_Table.Properties.VariableNames)
%                     Base_Table.Type = normalizeToCellstr(Base_Table.Type);
%                 end
%                 if ismember('Material', Base_Table.Properties.VariableNames)
%                     Base_Table.Material = normalizeToCellstr(Base_Table.Material);
%                 end
%                 if ismember('Reference_Material', Base_Table.Properties.VariableNames)
%                     Base_Table.Reference_Material = normalizeToCellstr(Base_Table.Reference_Material);
%                 end
% 
%                 numericCols = varfun(@isnumeric, Base_Table, 'OutputFormat', 'uniform');
%                 if any(numericCols)
%                     X = Base_Table{:, numericCols};
%                     X(X == 0) = NaN;
%                     Base_Table{:, numericCols} = X;
%                 end
%             end
% 
%             % Replace 0 with NaN in LOD-filtered composition columns (cell arrays with mixed numeric/string)
%             lodFilteredSuffixes = {'_mat_ugg', '_incl_ugg', '_mix_ugg'};
%             for colIdx = 1:width(Base_Table)
%                 colName = Base_Table.Properties.VariableNames{colIdx};
%                 if any(contains(colName, lodFilteredSuffixes))
%                     col = Base_Table.(colName);
%                     if iscell(col)
%                         for rowIdx = 1:numel(col)
%                             if isnumeric(col{rowIdx}) && col{rowIdx} == 0
%                                 col{rowIdx} = NaN;
%                             end
%                         end
%                         Base_Table.(colName) = col;
%                     end
%                 end
%             end
% 
%             % Replace empty doubles and 0 values in cell arrays with NaN
%             for colIdx = 1:width(Base_Table)
%                 col = Base_Table.(colIdx);
%                 if iscell(col)
%                     for rowIdx = 1:numel(col)
%                         cellVal = col{rowIdx};
%                         if isnumeric(cellVal) && isempty(cellVal)
%                             col{rowIdx} = NaN;
%                         elseif isnumeric(cellVal) && cellVal == 0
%                             col{rowIdx} = NaN;
%                         end
%                     end
%                     Base_Table{:, colIdx} = col;
%                 end
%             end
% 
%          % --- Row cleanup based on analyte availability (row-only; never drops columns) ---
%             if ~isempty(Base_Table)
%                 varNames = Base_Table.Properties.VariableNames;
% 
%                 % Isotope analyte columns with allowed output suffixes
%                 isotopePattern = '[A-Z][a-z]?\d+';  % e.g., Li7, Nd146, U238
%                 analyteSuffixPattern = '(?:_mat_ugg|_incl_ugg|_mix_ugg|_mat_lod|_incl_lod|_mat_cps|_incl_cps|_mix_cps|_sens)';
%                 isAnalyteCol = ~cellfun('isempty', regexp(varNames, ...
%                     ['^' isotopePattern analyteSuffixPattern '$'], 'once'));
% 
%                 analyteCols = varNames(isAnalyteCol);
%                 sensCols = analyteCols(endsWith(analyteCols, '_sens'));
%                 nonSensCols = setdiff(analyteCols, sensCols, 'stable');
% 
%                 hasSens = ~isempty(sensCols);
%                 hasNonSens = ~isempty(nonSensCols);
% 
%                 % Cases:
%                 % 1) no analyte columns -> no cleanup
%                 % 2) cleanup applies even if only _sens is in the table
%                 if ~isempty(analyteCols)
%                     nRows = height(Base_Table);
%                     removeMask = false(nRows,1);
% 
%                     % Core rule: remove row if all analyte columns are missing
%                     allAnalyteMissing = true(nRows,1);
%                     for c = 1:numel(analyteCols)
%                         allAnalyteMissing = allAnalyteMissing & isMissingByRow(Base_Table.(analyteCols{c}));
%                     end
%                     removeMask = removeMask | allAnalyteMissing;
% 
%                     % New rule: remove row if all _sens columns are missing 
%                     if hasSens
%                         allSensMissing = true(nRows, 1);
%                         for c = 1:numel(sensCols)
%                             allSensMissing = allSensMissing & isMissingByRow(Base_Table.(sensCols{c}));
%                         end
%                         removeMask = removeMask | allSensMissing;
%                     end
% 
%                     % Extra non-CAL rule: only when both _sens and non-_sens exist
%                     if hasSens && hasNonSens && ismember('Type', varNames)
%                         allNonSensMissing = true(nRows,1);
%                         for c = 1:numel(nonSensCols)
%                             allNonSensMissing = allNonSensMissing & isMissingByRow(Base_Table.(nonSensCols{c}));
%                         end
% 
%                         anySensPresent = false(nRows,1);
%                         for c = 1:numel(sensCols)
%                             anySensPresent = anySensPresent | ~isMissingByRow(Base_Table.(sensCols{c}));
%                         end
% 
%                         isCal = strcmpi(strtrim(string(Base_Table.Type)), "CAL");
%                         removeMask = removeMask | (~isCal & allNonSensMissing & anySensPresent);
%                     end
% 
%                     Base_Table(removeMask, :) = [];
%                 end
%             end
% 
%             % ---------------- Helpers ----------------
% 
%             function missingMask = isMissingByRow(col)
%                 if isnumeric(col)
%                     missingMask = isnan(col);
% 
%                 elseif isstring(col)
%                     missingMask = ismissing(col) | strlength(strtrim(col)) == 0;
% 
%                 elseif ischar(col)
%                     missingMask = cellfun(@(x) isempty(strtrim(x)), cellstr(col));
% 
%                 elseif iscell(col)
%                     n = numel(col);
%                     missingMask = false(n,1);
%                     for r = 1:n
%                         v = col{r};
% 
%                         if isempty(v)
%                             missingMask(r) = true;
% 
%                         elseif isnumeric(v)
%                             missingMask(r) = isscalar(v) && isnan(v);
% 
%                         elseif isstring(v)
%                             missingMask(r) = all(ismissing(v)) || all(strlength(strtrim(v)) == 0);
% 
%                         elseif ischar(v)
%                             missingMask(r) = isempty(strtrim(v));
% 
%                         else
%                             % Unsupported object types are treated as present
%                             missingMask(r) = false;
%                         end
%                     end
% 
%                 else
%                     % Fallback for unexpected column types
%                     missingMask = false(numel(col),1);
%                 end
% 
%                 missingMask = missingMask(:);
%             end
% 
%             function [assignmentMap, assignedCalList] = buildAnalyteCalMap(assignTbl)
%                 assignmentMap = containers.Map('KeyType', 'char', 'ValueType', 'char');
%                 assignedCalList = strings(0,1);
% 
%                 if isempty(assignTbl)
%                     return
%                 end
% 
%                 if istable(assignTbl)
%                     if width(assignTbl) < 2
%                         return
%                     end
%                     analyteCol = string(assignTbl{:,1});
%                     calCol = string(assignTbl{:,2});
%                 elseif iscell(assignTbl)
%                     if size(assignTbl,2) < 2
%                         return
%                     end
%                     analyteCol = string(assignTbl(:,1));
%                     calCol = string(assignTbl(:,2));
%                 else
%                     return
%                 end
% 
%                 analyteCol = strtrim(analyteCol(:));
%                 calCol = strtrim(calCol(:));
% 
%                 valid = strlength(analyteCol) > 0 & strlength(calCol) > 0;
%                 valid = valid & calCol ~= "None" & calCol ~= "Select Reference Material";
% 
%                 assignedCalList = unique(calCol(valid)); % intentionally no 'stable'
% 
%                 idx = find(valid);
%                 for ii = 1:numel(idx)
%                     assignmentMap(char(analyteCol(idx(ii)))) = char(calCol(idx(ii)));
%                 end
%             end
% 
%             function assignedTbl = buildAssignedAnalyteTable(nestedCell, b, assignmentMap_, validCALs_, analyteNames_, nRows_)
%                 assignedTbl = table('Size', [nRows_, numel(analyteNames_)], ...
%                     'VariableTypes', repmat("double", 1, numel(analyteNames_)), ...
%                     'VariableNames', cellstr(analyteNames_));
% 
%                 if isempty(nestedCell) || size(nestedCell,1) < b
%                     return
%                 end
% 
%                 for a = 1:numel(analyteNames_)
%                     analyteName = char(analyteNames_(a));
%                     outCol = zeros(nRows_,1);
% 
%                     if isKey(assignmentMap_, analyteName)
%                         calName = assignmentMap_(analyteName);
%                         calIdx = find(strcmp(validCALs_, calName), 1); % same pattern as update_assigned_table
% 
%                         if ~isempty(calIdx) && calIdx <= size(nestedCell,2)
%                             srcTab = nestedCell{b, calIdx};
%                             if istable(srcTab) && ismember(analyteName, srcTab.Properties.VariableNames)
%                                 srcCol = coerceNumericVector(srcTab.(analyteName), nRows_);
%                                 nCopy = min(numel(srcCol), nRows_);
%                                 outCol(1:nCopy) = srcCol(1:nCopy);
%                             end
%                         end
%                     end
% 
%                     assignedTbl.(analyteName) = outCol;
%                 end
%             end
% 
%             function vec = coerceNumericVector(vec, nRows_)
%                 if iscell(vec)
%                     tmp = zeros(numel(vec), 1);
%                     for ii = 1:numel(vec)
%                         v = vec{ii};
%                         if isnumeric(v) && isscalar(v) && ~isempty(v)
%                             tmp(ii) = v;
%                         else
%                             tmp(ii) = 0;
%                         end
%                     end
%                     vec = tmp;
%                 end
% 
%                 if ~isnumeric(vec)
%                     try
%                         vec = double(vec);
%                     catch
%                         try
%                             vec = str2double(string(vec));
%                             vec(isnan(vec)) = 0;
%                         catch
%                             vec = zeros(nRows_, 1);
%                         end
%                     end
%                 end
% 
%                 vec = vec(:);
%                 if isempty(vec)
%                     vec = zeros(nRows_, 1);
%                 end
%             end
% 
%             function val = getNestedValue(C, b, p, k, defaultVal)
%                 val = defaultVal;
%                 try
%                     if iscell(C) && numel(C) >= (p-1)*size(C,1)+b && ~isempty(C)
%                         X = C{b,p};
%                         if iscell(X)
%                             if numel(X) >= k && ~isempty(X{k})
%                                 val = X{k};
%                             end
%                         elseif isstring(X) || ischar(X)
%                             if numel(X) >= k
%                                 val = X(k);
%                             end
%                         elseif isnumeric(X)
%                             if numel(X) >= k
%                                 val = X(k);
%                             end
%                         end
%                     end
%                 catch
%                     val = defaultVal;
%                 end
%             end
% 
%             function T = ensureAnalyteTable(T, analyteNames_, nRows_)
%                 if ~istable(T) || isempty(T)
%                     T = table('Size', [nRows_, numel(analyteNames_)], ...
%                         'VariableTypes', repmat("double", 1, numel(analyteNames_)), ...
%                         'VariableNames', cellstr(analyteNames_));
%                     return
%                 end
% 
%                 for v = 1:width(T)
%                     if ~isnumeric(T{:,v})
%                         try
%                             T{:,v} = double(T{:,v});
%                         catch
%                             T{:,v} = zeros(height(T),1);
%                         end
%                     end
%                 end
% 
%                 if height(T) ~= nRows_
%                     headers = T.Properties.VariableNames;
%                     TT = table('Size', [nRows_, numel(headers)], ...
%                         'VariableTypes', repmat("double", 1, numel(headers)), ...
%                         'VariableNames', headers);
%                     nCopy = min(height(T), nRows_);
%                     if nCopy > 0
%                         TT(1:nCopy, :) = T(1:nCopy, :);
%                     end
%                     T = TT;
%                 end
%             end
% 
%             function sortedHeaders = getSortedHeaders(rowsCell)
%                 if isempty(rowsCell)
%                     sortedHeaders = {};
%                     return
%                 end
%                 isT = cellfun(@istable, rowsCell);
%                 if ~any(isT)
%                     sortedHeaders = {};
%                     return
%                 end
%                 headersCell = cellfun(@(t) t.Properties.VariableNames, rowsCell(isT), 'UniformOutput', false);
%                 if isempty(headersCell)
%                     sortedHeaders = {};
%                     return
%                 end
%                 allHeaders = unique([headersCell{:}], 'stable');
%                 nums = cellfun(@localExtractNum, allHeaders);
%                 [~, sortIdx] = sort(nums);
%                 sortedHeaders = allHeaders(sortIdx);
%             end
% 
%             function n = localExtractNum(h)
%                 tok = regexp(h, '\d+', 'match', 'once');
%                 if isempty(tok)
%                     n = NaN;
%                 else
%                     n = str2double(tok);
%                 end
%             end
% 
%             function tableWithAllColumns = addMissingColumns(inputTable, targetHeaders)
%                 if isempty(targetHeaders)
%                     tableWithAllColumns = inputTable;
%                     return
%                 end
%                 missingCols = setdiff(targetHeaders, inputTable.Properties.VariableNames, 'stable');
%                 if isempty(missingCols)
%                     tableWithAllColumns = inputTable(:, targetHeaders);
%                     return
%                 end
%                 missingData = zeros(height(inputTable), numel(missingCols));
%                 missingTable = array2table(missingData, 'VariableNames', missingCols);
%                 tableWithAllColumns = [inputTable missingTable];
%                 tableWithAllColumns = tableWithAllColumns(:, targetHeaders);
%             end
% 
%             function outTable = buildNumericBlock(rowsCell, label)
%                 if isempty(rowsCell)
%                     outTable = table();
%                     return
%                 end
%                 headers = getSortedHeaders(rowsCell);
%                 if ~isempty(rowsCell)
%                     rowsCell = cellfun(@(t) addMissingColumns(t, headers), rowsCell, 'UniformOutput', false);
%                 end
%                 if isempty(rowsCell)
%                     outTable = table();
%                     return
%                 end
%                 outTable = vertcat(rowsCell{:});
%                 if isempty(outTable)
%                     return
%                 end
%                 outTable = addvars(outTable, repmat({label}, height(outTable), 1), ...
%                     'Before', 1, 'NewVariableNames', 'Data_Source');
%             end
% 
%             function T = renameAnalyteColumns(T, suffix)
%                 if isempty(T)
%                     return
%                 end
%                 metaCols = {'Data_Source'};
%                 for v = 1:width(T)
%                     if ~ismember(T.Properties.VariableNames{v}, metaCols)
%                         T.Properties.VariableNames{v} = [T.Properties.VariableNames{v} suffix];
%                     end
%                 end
%             end
% 
%             function T = normalizeToCellstr(T)
%                 if istable(T) || isstruct(T)
%                     return
%                 end
%                 if iscell(T)
%                     try
%                         T = cellfun(@char, T, 'UniformOutput', false);
%                     catch
%                         T = cellfun(@(x) char(string(x)), T, 'UniformOutput', false);
%                     end
%                 else
%                     T = cellstr(string(T));
%                 end
%             end
% 
%             function C = getNestedField(spotStruct, fieldName, uniqueBracketIds_, unique_CALs_, nSpots_, defaultVal)
%                 if isfield(spotStruct, fieldName)
%                     C = spotStruct.(fieldName);
%                 else
%                     C = cell(numel(uniqueBracketIds_), numel(unique_CALs_));
%                     for b = 1:numel(uniqueBracketIds_)
%                         for p = 1:numel(unique_CALs_)
%                             if ischar(defaultVal) || isstring(defaultVal)
%                                 C{b, p} = repmat({defaultVal}, nSpots_, 1);
%                             else
%                                 C{b, p} = repmat(defaultVal, nSpots_, 1);
%                             end
%                         end
%                     end
%                 end
%             end
% 
%             function C = getNestedTable(spotStruct, fieldName, uniqueBracketIds_, unique_CALs_, nSpots_, analyteNames_)
%                 if isfield(spotStruct, fieldName)
%                     C = spotStruct.(fieldName);
%                 else
%                     C = cell(numel(uniqueBracketIds_), numel(unique_CALs_));
%                     for b = 1:numel(uniqueBracketIds_)
%                         for p = 1:numel(unique_CALs_)
%                             C{b, p} = table('Size', [nSpots_, numel(analyteNames_)], ...
%                                 'VariableTypes', repmat("double", 1, numel(analyteNames_)), ...
%                                 'VariableNames', cellstr(analyteNames_));
%                         end
%                     end
%                 end
%             end
% 
%             function C = getNestedMixingRatio(spotStruct, fieldName, uniqueBracketIds_, unique_CALs_, nSpots_)
%                 if isfield(spotStruct, fieldName)
%                     C = spotStruct.(fieldName);
%                 else
%                     C = cell(numel(uniqueBracketIds_), numel(unique_CALs_));
%                     for b = 1:numel(uniqueBracketIds_)
%                         for p = 1:numel(unique_CALs_)
%                             C{b, p} = repmat({NaN}, nSpots_, 1);
%                         end
%                     end
%                 end
%             end
% 
%             function val = getNestedMixingRatioValue(C, b, p, k)
%                 val = [];
%                 try
%                     if iscell(C) && numel(C) >= (p-1)*size(C,1)+b && ~isempty(C)
%                         X = C{b,p};
%                         if iscell(X)
%                             if numel(X) >= k && ~isempty(X{k})
%                                 val = X{k};
%                             else
%                                 val = NaN;
%                             end
%                         elseif isnumeric(X)
%                             if numel(X) >= k
%                                 val = X(k);
%                             else
%                                 val = NaN;
%                             end
%                         end
%                     end
%                 catch
%                     val = NaN;
%                 end
%             end
% 
%             function T = struct_to_table(S)
%                 if isempty(S)
%                     T = table();
%                     return
%                 end
%                 fields = fieldnames(S);
%                 lens = zeros(numel(fields),1);
%                 for o = 1:numel(fields)
%                     col = S.(fields{o});
%                     if ischar(col) || (isstring(col) && isscalar(col))
%                         lens(o) = 1;
%                     else
%                         lens(o) = numel(col);
%                     end
%                 end
%                 n = max(lens);
%                 T = table();
%                 for o = 1:numel(fields)
%                     col = S.(fields{o});
%                     if isempty(col)
%                         col = nan(n,1);
%                     elseif ischar(col) || (isstring(col) && isscalar(col))
%                         col = repmat({col}, n, 1);
%                     elseif iscell(col) && numel(col) == 1 && n > 1
%                         col = repmat(col, n, 1);
%                     elseif isnumeric(col) && numel(col) == 1 && n > 1
%                         col = repmat(col, n, 1);
%                     elseif numel(col) ~= n
%                         error('Field %s has %d elements, expected %d.', fields{o}, numel(col), n);
%                     end
%                     if isrow(col) && ~ischar(col)
%                         col = col';
%                     end
%                     T.(fields{o}) = col;
%                 end
%             end
% 
%             function harmonizedTables = harmonizeTableColumns(tableCell)
%                 if isempty(tableCell)
%                     harmonizedTables = tableCell;
%                     return
%                 end
% 
%                 allVarNames = {};
%                 for i = 1:numel(tableCell)
%                     if ~isempty(tableCell{i}) && istable(tableCell{i})
%                         allVarNames = [allVarNames, tableCell{i}.Properties.VariableNames];
%                     end
%                 end
%                 allVarNames = unique(allVarNames, 'stable');
% 
%                 for i = 1:numel(tableCell)
%                     tbl = tableCell{i};
%                     if isempty(tbl) || ~istable(tbl)
%                         continue
%                     end
% 
%                     existingVars = tbl.Properties.VariableNames;
%                     missingVars = setdiff(allVarNames, existingVars, 'stable');
% 
%                     for j = 1:numel(missingVars)
%                         varName = missingVars{j};
%                         nRows = height(tbl);
% 
%                         varType = 'double';
%                         for k = 1:numel(tableCell)
%                             if ~isempty(tableCell{k}) && istable(tableCell{k}) && ...
%                                     ismember(varName, tableCell{k}.Properties.VariableNames)
%                                 refCol = tableCell{k}.(varName);
%                                 varType = class(refCol);
%                                 break;
%                             end
%                         end
% 
%                         switch varType
%                             case {'double', 'single'}
%                                 tbl.(varName) = nan(nRows, 1);
%                             case 'cell'
%                                 tbl.(varName) = cell(nRows, 1);
%                             case {'char', 'string'}
%                                 tbl.(varName) = repmat({''}, nRows, 1);
%                             case 'datetime'
%                                 tbl.(varName) = NaT(nRows, 1);
%                             case 'logical'
%                                 tbl.(varName) = false(nRows, 1);
%                             otherwise
%                                 tbl.(varName) = cell(nRows, 1);
%                         end
%                     end
% 
%                     tbl = tbl(:, allVarNames);
%                     tableCell{i} = tbl;
%                 end
% 
%                 harmonizedTables = tableCell;
%             end
% 
%             function filteredCompTable = applyLODFiltering(compTable, lodTable, compSuffix, lodSuffix)
%                 filteredCompTable = compTable;
% 
%                 if isempty(compTable) || isempty(lodTable) || width(compTable) == 0 || width(lodTable) == 0
%                     return
%                 end
% 
%                 compCols = compTable.Properties.VariableNames;
%                 compCols = compCols(contains(compCols, compSuffix));
% 
%                 lodCols = lodTable.Properties.VariableNames;
%                 lodCols = lodCols(contains(lodCols, lodSuffix));
% 
%                 for l = 1:numel(compCols)
%                     elementName = strrep(compCols{l}, compSuffix, '');
%                     lodColName = [elementName, lodSuffix];
% 
%                     if ismember(lodColName, lodCols)
%                         comp_array = compTable.(compCols{l});
%                         lod_array = lodTable.(lodColName);
% 
%                         if isnumeric(comp_array) && isnumeric(lod_array)
%                             below_lod = comp_array < lod_array;
%                             display_values = num2cell(comp_array);
%                             display_values(below_lod) = arrayfun(@(x) ['<' num2str(x)], ...
%                                 lod_array(below_lod), 'UniformOutput', false);
%                             filteredCompTable.(compCols{l}) = display_values;
%                         end
%                     end
%                 end
%             end
%         
%         end
% 
%         function Base_Table = remove_masses_from_base_table(app, Base_Table)
% 
%             try
%                 oxide_file = dir(fullfile(app.sourceapp.default_oxides_path, 'Oxides.csv'));
% 
%                 % Get the full path of the file
%                 filePath = fullfile(oxide_file.folder, oxide_file.name);
% 
%                 opts = detectImportOptions(filePath);
%                 raw = readtable(filePath, opts);
% 
%             catch ME
%                 DisplayError(app, ME,'Error reading oxides from Oxides.csv for table builder submodule. Close it if it is open in another program. Make sure the Total Oxide folder was not moved or renamed.')
%                 message={'Error reading oxides from Oxides.csv. Did you rename or move the folder? Close the Oxides.csv if it is open in another program. If you got the base/project/session from someone else, adjust the total oxides folder path.'};
%                 uialert(app.TableBuilderUIFigure,message,' Error');
%                 disp(message)
% 
%             end
% 
%             % Extract the data
%             element_names = table2cell(raw(:,1));  % Convert to cell array
%             preferred_isotope = raw(:,4);
% 
%             % Store element names as cell array
%             preferred_isotopes = table2cell(preferred_isotope);
% 
%             % Create a mapping of elements to their preferred isotopes
%             preferred_iso_map = containers.Map();
% 
%             for i = 1:height(raw)  % Use height() for tables, or length(element_names) now that it's a cell array
%                 elem = element_names{i};
%                 pref_iso = preferred_isotopes{i};
%                 if ~isempty(pref_iso)
%                     preferred_iso_map(elem) = pref_iso;
%                 end
%             end
% 
%             % Get all column names
%             col_names = Base_Table.Properties.VariableNames;
% 
%             % Identify which columns to keep and how to rename them
%             cols_to_remove = {};
%             cols_to_rename = containers.Map();
% 
%             % Group columns by suffix (e.g., _incl_ugg, _mat_cps)
%             % and track which isotopes we've encountered for each element
%             isotope_pattern = '([A-Z][a-z]?)(\d+)(.*)';  % Matches: Na23_incl_ugg
% 
%             processed_elements = containers.Map();  % Track which elements we've already processed
% 
%             for i = 1:length(col_names)
%                 col_name = col_names{i};
%                 tokens = regexp(col_name, isotope_pattern, 'tokens');
% 
%                 if isempty(tokens)
%                     continue;  % Skip columns that don't match isotope pattern
%                 end
% 
%                 % Extract: element (Na), mass (23), suffix (_incl_ugg)
%                 element = tokens{1}{1};
%                 mass_num = tokens{1}{2};
%                 suffix = tokens{1}{3};
% 
%                 % Create a key for this element+suffix combination
%                 key = [element '_' strrep(suffix, '_', '')];
% 
%                 if ~isKey(processed_elements, key)
%                     % First time seeing this element+suffix combination
%                     % Check if there's a preferred isotope
%                     if isKey(preferred_iso_map, element)
%                         pref = preferred_iso_map(element);
%                         pref_mass = regexp(pref, '\d+', 'match');
%                         if strcmp(mass_num, pref_mass{1})
%                             % This is the preferred isotope, keep it
%                             new_name = [element suffix];
%                             cols_to_rename(col_name) = new_name;
%                             processed_elements(key) = col_name;
%                         else
%                             % Not the preferred isotope, mark for removal
%                             cols_to_remove = [cols_to_remove, col_name];
%                         end
%                     else
%                         % No preference specified, keep first occurrence
%                         new_name = [element suffix];
%                         cols_to_rename(col_name) = new_name;
%                         processed_elements(key) = col_name;
%                     end
%                 else
%                     % We've already seen this element+suffix combination
%                     % Remove this duplicate
%                     cols_to_remove = [cols_to_remove, col_name];
%                 end
%             end
% 
%             % Remove columns that should be deleted
%             Base_Table(:, cols_to_remove) = [];
% 
%             % Rename columns
%             rename_keys = keys(cols_to_rename);
%             for i = 1:length(rename_keys)
%                 old_name = rename_keys{i};
%                 new_name = cols_to_rename(old_name);
%                 Base_Table = renamevars(Base_Table, old_name, new_name);
%             end
% 
% 
%         end

%         Old function without assignment
%         function Base_Table = build_table_from_base(app)
%             % --- Get checked nodes from SpotMetaTree ---
%             checkedMetaNodes = app.SpotMetaTree.CheckedNodes;
%             if isempty(checkedMetaNodes)
%                 Base_Table = table();
%                 return
%             end
%             checkedMetaLabels = string({checkedMetaNodes.NodeData}); % assumes NodeData is string or char
% 
%             % Helper to check if a label is checked
%             isChecked = @(label) any(checkedMetaLabels == label);
% 
%             % --- Always needed: Metadata ---
%             allProjects = {};
%             allSessions = {};
%             allComments = {};
%             allAnalysts = {};
%             allProcessors = {};
%             allSpotnames = {};
%             alldates = {};
%             % This will hold all dynamic metadata columns
%             dynamicMetaData = struct();
% 
%             allTypes = {};
%             allRefMaterials = {};
%             allIntTimePoints = {};
%             allQuantifiedWith = {};
%             allusedBracket_id = {};
% 
%             % Internal standardization
%             allIntStdElements = {};
%             allIntStdValues = {};
%             allIntStdOxValues = {};
%             allFeRatios = {};
%             allIntStdElements_Incl = {};
%             allIntStdValues_Incl = {};
%             allSecondIntStdElements_Incl = {};
%             allSecondIntStdValues_Incl = {};
%             allIntStdOxValues_Incl = {};
%             allFeRatios_Incl = {};
% 
%             % Host-Inclusion-unmixing
%             allUnmixingVia = {};
%             allMatrixOnlyTracer = {};
%             allMatrixFromSpot = {};
%             allMixingRatios = {};
% 
%             % Sensitivities
%             allSensitivitiesRows = {};
%             % Matrix/Host mass fractions
%             allmatrixcompRows = {};
%             % Inclusion mass fractions
%             allinclcompRows = {};
%             % Matrix/Host limits of detection
%             allmatrixlodRows = {};
%             % Inclusion limits of detection
%             allincllodRows = {};
%             % Mix mass fractions
%             allmixcompRows = {};
% 
%             % cps
%             allbkgdCpsRows_mat = {};
%             allbkgdCpsRows_incl = {};
%             allbkgdCpsRows_mix = {};
% 
%             % LA parameters
%             allLaserParamTables = {};
%             % ICP-MS parameters
%             allICPMSParamTables = {};
% 
%             % Get checked nodes from the project/session tree
%             checkedNodes = app.ProjectSessionTree.CheckedNodes;
%             checkedIndices = [];
%             for n = 1:numel(checkedNodes)
%                 nodeData = checkedNodes(n).NodeData;
%                 if ~isempty(nodeData) && numel(nodeData) == 2 && nodeData(2) > 0
%                     checkedIndices = [checkedIndices; nodeData];
%                 end
%             end
% 
%                     % --- Pre-scan all selected sessions to find all dynamic metadata fields ---
%             for i = 1:numel(app.base)
%                 for j = 1:numel(app.base(i).project)
%                     if isempty(checkedIndices) || ~ismember([i, j], checkedIndices, 'rows')
%                         continue
%                     end
%                     if isfield(app.base(i).project(j), 'session')
%                         session = app.base(i).project(j).session;
%                     else
%                         continue
%                     end
%                     
%                     if ~isfield(session, 'spot') || isempty(session.spot)
%                         continue
%                     end
%                     nSpots = numel(session.spot.name);
% 
%                     % Scan for all dynamic metadata fields in this session
%                     if isfield(session, 'meta') && isstruct(session.meta)
%                         metaFields = fieldnames(session.meta);
%                         for f = 1:numel(metaFields)
%                             fieldName = metaFields{f};
%                             % Check if it's a per-spot field (cell array with nRows)
%                             if iscell(session.meta.(fieldName)) && size(session.meta.(fieldName), 1) == nSpots
%                                 if ~isfield(dynamicMetaData, fieldName)
%                                     dynamicMetaData.(fieldName) = {}; % Initialize if not present
%                                 end
%                             end
%                         end
%                     end
%                 end
%             end
%             
%             dynamicMetaFields = fieldnames(dynamicMetaData);
% 
%             for i = 1:numel(app.base)
%                 projectName = app.base(i).project_name;
%                 for j = 1:numel(app.base(i).project)
%                     if isempty(checkedIndices) || ~ismember([i, j], checkedIndices, 'rows')
%                         continue
%                     end
%                     if isfield(app.base(i).project(j), 'session')
%                         session = app.base(i).project(j).session;
%                     else
%                         continue
%                     end
% 
%                     % Skip if any module status is 0
%                     if any(structfun(@(x) x == 0, session.config.module_status))
%                         continue
%                     end
% 
%                     % Session meta
%                     if isfield(session, 'session_name') && ~isempty(session.session_name)
%                         sessionName = char(session.session_name);
%                     else
%                         sessionName = '';
%                     end
%                     if isfield(session, 'meta') && isfield(session.meta, 'date') && ~isempty(session.meta.date)
%                         sessiondate = session.meta.date;
%                     else
%                         sessiondate = '';
%                     end
%                     if isfield(session.meta, 'session_comment') && ~isempty(session.meta.session_comment)
%                         sessionComment = char(session.meta.session_comment);
%                     else
%                         sessionComment = '';
%                     end
%                     if isfield(session.meta, 'analyst') && ~isempty(session.meta.analyst)
%                         analyst = char(session.meta.analyst);
%                     else
%                         analyst = '';
%                     end
%                     if isfield(session.meta, 'processed_by') && ~isempty(session.meta.processed_by)
%                         processed_by = char(session.meta.processed_by);
%                     else
%                         processed_by = '';
%                     end
% 
%                     % Spot block
%                     if ~isfield(session, 'spot') || isempty(session.spot)
%                         continue
%                     end
% 
%                     spotnames = session.spot.name;
%                     nSpots = numel(spotnames);
% 
%                     % --- Extract dynamic metadata for the current session ---
%                     sessionDynamicData = struct();
%                     for f = 1:numel(dynamicMetaFields)
%                         fieldName = dynamicMetaFields{f};
%                         if isfield(session, 'meta') && isfield(session.meta, fieldName) && ~isempty(session.meta.(fieldName))
%                             sessionDynamicData.(fieldName) = session.meta.(fieldName)(:);
%                         else
%                             sessionDynamicData.(fieldName) = repmat({''}, nSpots, 1);
%                         end
%                     end
% 
%                     % Type and CALs
%                     % Change "PRM" to "CAL" & "SRM" to "QCRM"-Backwards compatibility
%                     session.spot.type(session.spot.type == "PRM") = "CAL";
%                     session.spot.type(session.spot.type == "SRM") = "QCRM";
% 
%                     % Change session.spot.prm_sensitivities to session.spot.cal_sensitivities
%                     if isfield(session.spot, 'prm_sensitivities')
%                         % Copy data to the new field
%                         [session.spot.cal_sensitivities] = session.spot.prm_sensitivities;
%                         % Remove the old field
%                         session.spot = rmfield(session.spot, 'prm_sensitivities');
%                     end
% 
%                     if isfield(session.spot, 'type') && ~isempty(session.spot.type)
%                         type = string(session.spot.type(:));
%                     else
%                         type = strings(nSpots, 1);
%                     end
%                     if isfield(session.spot, 'referencematerial') && ~isempty(session.spot.referencematerial)
%                         referencematerial = string(session.spot.referencematerial(:));
%                     else
%                         referencematerial = strings(nSpots, 1);
%                     end
% 
%                     % Determine CALs from type == "CAL"
%                     cal_indices = (type == "CAL");
%                     if any(cal_indices)
%                         cal_materials = referencematerial(cal_indices);
%                         unique_CALs = unique(cal_materials);
%                     else
%                         unique_CALs = string.empty(0,1);
%                     end
% 
%                     % Bracket IDs
%                     if isfield(session.spot, 'bracket_id') && ~isempty(session.spot.bracket_id)
%                         bracket_id = session.spot.bracket_id(:);
%                         if ~isnumeric(bracket_id)
%                             bracket_id = zeros(nSpots,1);
%                         end
%                         uniqueBracketIds = unique(bracket_id);
%                         uniqueBracketIds = uniqueBracketIds(uniqueBracketIds ~= 0);
%                     else
%                         bracket_id = zeros(nSpots, 1); % 0 marks missing
%                         uniqueBracketIds = [];
%                     end
% 
%                     % Integer time points
%                     if isfield(session.spot, 'integer_time_points') && ~isempty(session.spot.integer_time_points)
%                         integer_time_points = session.spot.integer_time_points(:);
%                         if ~isnumeric(integer_time_points)
%                             integer_time_points = NaN(nSpots,1);
%                         end
%                     else
%                         integer_time_points = NaN(nSpots, 1);
%                     end
% 
%                     % Analyte names
%                     if isfield(session.spot, 'analytes') && ~isempty(session.spot.analytes)
%                         analyteNames = strtrim(string(session.spot.analytes(:)));
%                     else
%                         analyteNames = strings(0,1);
%                     end
% 
%                     % --- Only process blocks if checked in SpotMetaTree ---
%                     % LA parameters
%                     laserParamTable = table();
%                     if isChecked("LA parameters")
%                         if isfield(session, 'meta') && isfield(session.meta, 'laser_parameters')
%                             laserParamStruct = session.meta.laser_parameters;
%                             
%                             laserParamTable = struct_to_table(laserParamStruct);
%  
%                         else
%                             laserParamTable = table();
%                         end
%                     end
% 
%                     % ICP-MS parameters
%                     ICPMSParamTable = table();
%                     if isChecked("ICP-MS parameters")
%                         if isfield(session, 'meta') && isfield(session.meta, 'icp_ms_parameters')
%                             ICPMSParamStruct = session.meta.icp_ms_parameters;
%                             ICPMSParamTable = struct_to_table(ICPMSParamStruct);
%                         else
%                             ICPMSParamTable = table();
%                         end
%                     end
% 
%                     % Internal standardization
%                     if isChecked("Internal standardization")
%                         internal_standard_element = getNestedField(session.spot, 'internal_standard', uniqueBracketIds, unique_CALs, nSpots, "");
%                         internal_standard_value = getNestedField(session.spot, 'internal_standard_mass_fraction', uniqueBracketIds, unique_CALs, nSpots, NaN);
%                         internal_standard_value_ox = getNestedField(session.spot, 'internal_standard_oxide_wt', uniqueBracketIds, unique_CALs, nSpots, NaN);
%                         Fe_ratio_for_total_oxide = getNestedField(session.spot, 'Fe_ratio_for_total_oxide', uniqueBracketIds, unique_CALs, nSpots, NaN);
%                         internal_standard_inclusion = getNestedField(session.spot, 'internal_standard_inclusion', uniqueBracketIds, unique_CALs, nSpots, "");
%                         internal_standard_mass_fraction_inclusion = getNestedField(session.spot, 'internal_standard_mass_fraction_inclusion', uniqueBracketIds, unique_CALs, nSpots, NaN);
%                         second_internal_standard_inclusion = getNestedField(session.spot, 'second_internal_standard_inclusion', uniqueBracketIds, unique_CALs, nSpots, "");
%                         second_internal_standard_mass_fraction_inclusion = getNestedField(session.spot, 'second_internal_standard_mass_fraction_inclusion', uniqueBracketIds, unique_CALs, nSpots, NaN);
%                         internal_standard_oxide_wt_inclusion = getNestedField(session.spot, 'internal_standard_oxide_wt_inclusion', uniqueBracketIds, unique_CALs, nSpots, NaN);
%                         Fe_ratio_for_total_oxide_inclusion = getNestedField(session.spot, 'Fe_ratio_for_total_oxide_inclusion', uniqueBracketIds, unique_CALs, nSpots, NaN);
%                     end
% 
%                     % Sensitivities
%                     if isChecked("Sensitivities")
%                         if isfield(session.spot, 'cal_sensitivities')
%                             cal_sensitivities = session.spot.cal_sensitivities;
%                         end
%                         sensitivities = getNestedTable(session.spot, 'sensitivities', uniqueBracketIds, unique_CALs, nSpots, analyteNames);
%                     end
% 
%                     % Matrix/Host mass fractions
%                     if isChecked("Matrix/Host mass fractions")
%                         matrix_composition = getNestedTable(session.spot, 'matrix_composition', uniqueBracketIds, unique_CALs, nSpots, analyteNames);
%                     end
% 
%                     % Matrix/Host limits of detection
%                     if isChecked("Matrix/Host limits of detection") || isChecked("Matrix/Host mass fractions")
%                         matrix_limits_of_detection = getNestedTable(session.spot, 'matrix_limits_of_detection', uniqueBracketIds, unique_CALs, nSpots, analyteNames);
%                     end
% 
%                     % Inclusion blocks
%                     if isChecked("Inclusion mass fractions")
%                         inclusion_composition = getNestedTable(session.spot, 'inclusion_composition', uniqueBracketIds, unique_CALs, nSpots, analyteNames);
%                     end
%                     if isChecked("Inclusion limits of detection") || isChecked("Inclusion mass fractions") || isChecked("Mix mass fractions")
%                         inclusion_limits_of_detection = getNestedTable(session.spot, 'inclusion_limits_of_detection', uniqueBracketIds, unique_CALs, nSpots, analyteNames);
%                     end
%                     if isChecked("Mix mass fractions")
%                         mix_composition = getNestedTable(session.spot, 'mix_composition', uniqueBracketIds, unique_CALs, nSpots, analyteNames);
%                     end
% 
%                     % Mixing ratios
%                     if isChecked("Host-Inclusion-unmixing")
%                         mixing_ratio = getNestedMixingRatio(session.spot, 'mixing_ratio', uniqueBracketIds, unique_CALs, nSpots);
%                         % Host-Inclusion-unmixing
%                         if isfield(session.spot, 'unmixing_via') && ~isempty(session.spot.unmixing_via)
%                             unmixing_via = string(session.spot.unmixing_via(:));
%                         else
%                             unmixing_via = repmat({''}, nSpots, 1);
%                         end
%                         if isfield(session.spot, 'matrix_only_tracer') && ~isempty(session.spot.matrix_only_tracer)
%                             matrix_only_tracer = string(session.spot.matrix_only_tracer(:));
%                         else
%                             matrix_only_tracer = repmat({''}, nSpots, 1);
%                         end
%                         if isfield(session.spot, 'matrix_from_spot') && ~isempty(session.spot.matrix_from_spot)
%                             matrix_from_spot = string(session.spot.matrix_from_spot(:));
%                         else
%                             matrix_from_spot = repmat({''}, nSpots, 1);
%                         end
%                     end
% 
%                     % cps
%                     %if isChecked("Matrix/Host counts-per-sweep") % this is
%                     %commented so the laser_parameters and icp_ms_parameters knows how to fill the empty
%                     %tables
%                     if isfield(app.base(i).project(j).session.spot, 'bkgd_corrected_cps')
%                         bkgdCps_mat = app.base(i).project(j).session.spot.bkgd_corrected_cps;
%                     else
%                         bkgdCps_mat = table();
%                     end
%                     %end
%                     if isChecked("Inclusion counts-per-sweep")
%                         if isfield(app.base(i).project(j).session.spot, 'bkgd_corrected_cps_incl')
%                             bkgdCps_incl = app.base(i).project(j).session.spot.bkgd_corrected_cps_incl;
%                         else
%                             bkgdCps_incl = array2table(zeros(numel(spotnames), numel(app.base(i).project(j).session.spot.analytes)), ...
%                                 'VariableNames', app.base(i).project(j).session.spot.analytes);
%                         end
%                     end
%                     if isChecked("Mix counts-per-sweep")
%                         if isfield(app.base(i).project(j).session.spot, 'bkgd_corrected_cps_mix')
%                             bkgdCps_mix = app.base(i).project(j).session.spot.bkgd_corrected_cps_mix;
%                         else
%                             bkgdCps_mix = array2table(zeros(numel(spotnames), numel(app.base(i).project(j).session.spot.analytes)), ...
%                                 'VariableNames', app.base(i).project(j).session.spot.analytes);
%                         end
%                     end
% 
%                     % Skip if no CALs or no brackets (as requested: disregard empties)
%                     if isempty(unique_CALs) || isempty(uniqueBracketIds)
%                         continue
%                     end
% 
%                     % Duplicate per CAL and per bracket
%                     for p = 1:numel(unique_CALs)
%                         for b = 1:numel(uniqueBracketIds)
%                             for k = 1:nSpots
%                                 allProjects{end+1,1} = projectName;
%                                 allSessions{end+1,1} = sessionName;
%                                 allComments{end+1,1} = sessionComment;
%                                 allAnalysts{end+1,1} = analyst;
%                                 allProcessors{end+1,1} = processed_by;
% 
%                                 alldates{end+1,1} = sessiondate;
%                                 allSpotnames{end+1,1} = spotnames{k};
% 
%                                 % Append dynamic metadata
%                                 for f = 1:numel(dynamicMetaFields)
%                                     fieldName = dynamicMetaFields{f};
%                                     currentColumn = sessionDynamicData.(fieldName);
%                                     dynamicMetaData.(fieldName){end+1,1} = currentColumn{k};
%                                 end
% 
%                                 if numel(type) >= k && ~ismissing(type(k))
%                                     allTypes{end+1,1} = type(k);
%                                 else
%                                     allTypes{end+1,1} = "";
%                                 end
%                                 if numel(referencematerial) >= k && ~ismissing(referencematerial(k))
%                                     allRefMaterials{end+1,1} = referencematerial(k);
%                                 else
%                                     allRefMaterials{end+1,1} = "";
%                                 end
%                                 allIntTimePoints{end+1,1} = integer_time_points(k);
%                                 allQuantifiedWith{end+1,1} = unique_CALs(p);
%                                 allusedBracket_id{end+1,1} = uniqueBracketIds(b);
% 
%                                 % Internal standardization
%                                 if isChecked("Internal standardization")
%                                     allIntStdElements{end+1,1}     = getNestedValue(internal_standard_element, b, p, k, "");
%                                     allIntStdValues{end+1,1}       = getNestedValue(internal_standard_value, b, p, k, NaN);
%                                     allIntStdOxValues{end+1,1}     = getNestedValue(internal_standard_value_ox, b, p, k, NaN);
%                                     allFeRatios{end+1,1}           = getNestedValue(Fe_ratio_for_total_oxide, b, p, k, NaN);
%                                     allIntStdElements_Incl{end+1,1} = getNestedValue(internal_standard_inclusion, b, p, k, "");
%                                     allIntStdValues_Incl{end+1,1}   = getNestedValue(internal_standard_mass_fraction_inclusion, b, p, k, NaN);
%                                     allSecondIntStdElements_Incl{end+1,1} = getNestedValue(second_internal_standard_inclusion, b, p, k, "");
%                                     allSecondIntStdValues_Incl{end+1,1}   = getNestedValue(second_internal_standard_mass_fraction_inclusion, b, p, k, NaN);
%                                     allIntStdOxValues_Incl{end+1,1}       = getNestedValue(internal_standard_oxide_wt_inclusion, b, p, k, NaN);
%                                     allFeRatios_Incl{end+1,1}             = getNestedValue(Fe_ratio_for_total_oxide_inclusion, b, p, k, NaN);
%                                 end
%                                 if isChecked("Host-Inclusion-unmixing")
%                                     allMixingRatios{end+1,1} = getNestedMixingRatioValue(mixing_ratio, b, p, k);
%                                     allUnmixingVia{end+1,1} = unmixing_via{k};
%                                     allMatrixOnlyTracer{end+1,1} = matrix_only_tracer{k};
%                                     allMatrixFromSpot{end+1,1} = matrix_from_spot{k};
%                                 end
%                             end
% 
%                             % Sensitivities
%                             if isChecked("Sensitivities")
% 
%                                 % Update sensitivities table with cal_sensitivities values
%                                 sens_tbl = sensitivities{b, p};
%                                 common_cols = intersect(sens_tbl.Properties.VariableNames, cal_sensitivities.Properties.VariableNames);
%                                 nonzero_rows = any(table2array(cal_sensitivities) ~= 0, 2);
%                                 for col = common_cols
%                                     sens_tbl{nonzero_rows, col} = cal_sensitivities{nonzero_rows, col};
%                                 end
%                                 sensitivities{b, p} = sens_tbl;
% 
% 
%                                 allSensitivitiesRows{end+1,1} = ensureAnalyteTable(sensitivities{b,p}, analyteNames, nSpots);
%                             end
%                             % Matrix/Host mass fractions
%                             if isChecked("Matrix/Host mass fractions")
%                                 allmatrixcompRows{end+1,1}   = ensureAnalyteTable(matrix_composition{b,p}, analyteNames, nSpots);
%                             end
%                             % Matrix/Host limits of detection
%                             if isChecked("Matrix/Host limits of detection") || isChecked("Matrix/Host mass fractions")
%                                 allmatrixlodRows{end+1,1}    = ensureAnalyteTable(matrix_limits_of_detection{b,p}, analyteNames, nSpots);
%                             end
%                             % Inclusion mass fractions
%                             if isChecked("Inclusion mass fractions")
%                                 allinclcompRows{end+1,1}     = ensureAnalyteTable(inclusion_composition{b,p}, analyteNames, nSpots);
%                             end
%                             % Inclusion limits of detection
%                             if isChecked("Inclusion limits of detection") || isChecked("Inclusion mass fractions") || isChecked("Mix mass fractions")
%                                 allincllodRows{end+1,1}      = ensureAnalyteTable(inclusion_limits_of_detection{b,p}, analyteNames, nSpots);
%                             end
%                             % Mix mass fractions
%                             if isChecked("Mix mass fractions")
%                                 allmixcompRows{end+1,1}      = ensureAnalyteTable(mix_composition{b,p}, analyteNames, nSpots);
%                             end
% 
%                             % cps
%                             %if isChecked("Matrix/Host counts-per-sweep")
%                             allbkgdCpsRows_mat{end+1,1} = bkgdCps_mat;
%                             %end
%                             if isChecked("Inclusion counts-per-sweep")
%                                 allbkgdCpsRows_incl{end+1,1} = bkgdCps_incl;
%                             end
%                             if isChecked("Mix counts-per-sweep")
%                                 allbkgdCpsRows_mix{end+1,1} = bkgdCps_mix;
%                             end
% 
%                             % LA/ICPMS param tables
%                             if isChecked("LA parameters")
%                                 allLaserParamTables{end+1,1} = laserParamTable;
%                             end
%                             if isChecked("ICP-MS parameters")
%                                 allICPMSParamTables{end+1,1} = ICPMSParamTable;
%                             end
%                         end
%                     end
%                 end
%             end
% 
% 
%             % --- Build Metadata Table (always included) ---
%             Metadata_Table = table(allProjects, allSessions, allComments, allAnalysts, allProcessors, alldates, allSpotnames, ...
%                 allTypes, allRefMaterials, ...
%                 allIntTimePoints, ...
%                 allQuantifiedWith, allusedBracket_id, ...
%                 'VariableNames', {'Project', 'Session', 'Comment','Analyst', 'Processed by', 'Date', 'Spotname', ...
%                 'Type', 'Reference_Material', ...
%                 'Integer_Time_Points', ...
%                 'Quantified_with', 'used_bracket_id'});
% 
%             % Add dynamic metadata columns to the table
%             for f = 1:numel(dynamicMetaFields)
%                 fieldName = dynamicMetaFields{f};
%                 Metadata_Table.(fieldName) = dynamicMetaData.(fieldName);
%             end
% 
%             % --- Build only selected numeric/parameter blocks ---
%             tableBlocks = {Metadata_Table};
% 
%             if isChecked("Internal standardization")
%                 % Add internal standardization columns to Metadata_Table
%                 Metadata_Table.Internal_Standard_Element = allIntStdElements;
%                 Metadata_Table.Internal_Standard_Value = allIntStdValues;
%                 Metadata_Table.Internal_Standard_Oxide_Value = allIntStdOxValues;
%                 Metadata_Table.Fe_Ratio_For_Total_Oxide = allFeRatios;
%                 Metadata_Table.Internal_Standard_Element_Inclusion = allIntStdElements_Incl;
%                 Metadata_Table.Internal_Standard_Value_Inclusion = allIntStdValues_Incl;
%                 Metadata_Table.Second_Internal_Standard_Element_Inclusion = allSecondIntStdElements_Incl;
%                 Metadata_Table.Second_Internal_Standard_Value_Inclusion = allSecondIntStdValues_Incl;
%                 Metadata_Table.Internal_Standard_Oxide_Value_Inclusion = allIntStdOxValues_Incl;
%                 Metadata_Table.Fe_Ratio_For_Total_Oxide_Inclusion = allFeRatios_Incl;
%             end
% 
%             if isChecked("Host-Inclusion-unmixing")
%                 Metadata_Table.Mixing_Ratios = allMixingRatios;
%                 Metadata_Table.Unmixing_Via = allUnmixingVia;
%                 Metadata_Table.Matrix_Only_Tracer = allMatrixOnlyTracer;
%                 Metadata_Table.Matrix_From_Spot = allMatrixFromSpot;
%             end
% 
%             if isChecked("Matrix/Host mass fractions")
% 
%                 allmatrixlodTable = buildNumericBlock(allmatrixlodRows, 'Matrix_LOD');
%                 allmatrixlodTable = renameAnalyteColumns(allmatrixlodTable, '_mat_lod');
%                 if ismember('Data_Source', allmatrixlodTable.Properties.VariableNames)
%                     allmatrixlodTable.Data_Source = [];
%                 end
% 
% 
%                 allmatrixcompTable = buildNumericBlock(allmatrixcompRows, 'Matrix_Composition');
%                 allmatrixcompTable = renameAnalyteColumns(allmatrixcompTable, '_mat_ugg');
%                 if ismember('Data_Source', allmatrixcompTable.Properties.VariableNames)
%                     allmatrixcompTable.Data_Source = [];
%                 end
% 
%                 % Apply LOD filtering to matrix composition
%                 allmatrixcompTable = applyLODFiltering(allmatrixcompTable, allmatrixlodTable, '_mat_ugg', '_mat_lod');
% 
%                 tableBlocks{end+1} = allmatrixcompTable;
%             end
% 
%             if isChecked("Inclusion mass fractions")
% 
%                 allinclusionlodTable = buildNumericBlock(allincllodRows, 'Inclusion_LOD');
%                 allinclusionlodTable = renameAnalyteColumns(allinclusionlodTable, '_incl_lod');
%                 if ismember('Data_Source', allinclusionlodTable.Properties.VariableNames)
%                     allinclusionlodTable.Data_Source = [];
%                 end
% 
%                 allinclusioncompTable = buildNumericBlock(allinclcompRows, 'Inclusion_Composition');
%                 allinclusioncompTable = renameAnalyteColumns(allinclusioncompTable, '_incl_ugg');
%                 if ismember('Data_Source', allinclusioncompTable.Properties.VariableNames)
%                     allinclusioncompTable.Data_Source = [];
%                 end
% 
%                 % Apply LOD filtering to inclusion composition
%                 allinclusioncompTable = applyLODFiltering(allinclusioncompTable, allinclusionlodTable, '_incl_ugg', '_incl_lod');
% 
%                 tableBlocks{end+1} = allinclusioncompTable;
%             end
% 
%             if isChecked("Mix mass fractions")
% 
%                 allinclusionlodTable = buildNumericBlock(allincllodRows, 'Inclusion_LOD');
%                 allinclusionlodTable = renameAnalyteColumns(allinclusionlodTable, '_incl_lod');
%                 if ismember('Data_Source', allinclusionlodTable.Properties.VariableNames)
%                     allinclusionlodTable.Data_Source = [];
%                 end
% 
%                 allmixcompTable = buildNumericBlock(allmixcompRows, 'Mix_Composition');
%                 allmixcompTable = renameAnalyteColumns(allmixcompTable, '_mix_ugg');
%                 if ismember('Data_Source', allmixcompTable.Properties.VariableNames)
%                     allmixcompTable.Data_Source = [];
%                 end
% 
%                 % Apply LOD filtering to inclusion composition
%                 allmixcompTable = applyLODFiltering(allmixcompTable, allinclusionlodTable, '_mix_ugg', '_incl_lod');
% 
%                 tableBlocks{end+1} = allmixcompTable;
%             end
% 
%             if isChecked("Matrix/Host limits of detection")
%                 allmatrixlodTable = buildNumericBlock(allmatrixlodRows, 'Matrix_LOD');
%                 allmatrixlodTable = renameAnalyteColumns(allmatrixlodTable, '_mat_lod');
%                 if ismember('Data_Source', allmatrixlodTable.Properties.VariableNames)
%                     allmatrixlodTable.Data_Source = [];
%                 end
%                 tableBlocks{end+1} = allmatrixlodTable;
%             end
% 
%             if isChecked("Inclusion limits of detection")
%                 allinclusionlodTable = buildNumericBlock(allincllodRows, 'Inclusion_LOD');
%                 allinclusionlodTable = renameAnalyteColumns(allinclusionlodTable, '_incl_lod');
%                 if ismember('Data_Source', allinclusionlodTable.Properties.VariableNames)
%                     allinclusionlodTable.Data_Source = [];
%                 end
%                 tableBlocks{end+1} = allinclusionlodTable;
%             end
% 
%             if isChecked("Matrix/Host counts-per-sweep")
%                 allbkgdCpsTable_mat = buildNumericBlock(allbkgdCpsRows_mat, 'Bkgd_Corrected_CPS_mat');
%                 allbkgdCpsTable_mat = renameAnalyteColumns(allbkgdCpsTable_mat, '_mat_cps');
%                 if ismember('Data_Source', allbkgdCpsTable_mat.Properties.VariableNames)
%                     allbkgdCpsTable_mat.Data_Source = [];
%                 end
%                 tableBlocks{end+1} = allbkgdCpsTable_mat;
%             end
%             if isChecked("Inclusion counts-per-sweep")
%                 allbkgdCpsTable_incl = buildNumericBlock(allbkgdCpsRows_incl, 'Bkgd_Corrected_CPS_incl');
%                 allbkgdCpsTable_incl = renameAnalyteColumns(allbkgdCpsTable_incl, '_incl_cps');
%                 if ismember('Data_Source', allbkgdCpsTable_incl.Properties.VariableNames)
%                     allbkgdCpsTable_incl.Data_Source = [];
%                 end
%                 tableBlocks{end+1} = allbkgdCpsTable_incl;
%             end
%             if isChecked("Mix counts-per-sweep")
%                 allbkgdCpsTable_mix = buildNumericBlock(allbkgdCpsRows_mix, 'Bkgd_Corrected_CPS_mix');
%                 allbkgdCpsTable_mix = renameAnalyteColumns(allbkgdCpsTable_mix, '_mix_cps');
%                 if ismember('Data_Source', allbkgdCpsTable_mix.Properties.VariableNames)
%                     allbkgdCpsTable_mix.Data_Source = [];
%                 end
%                 tableBlocks{end+1} = allbkgdCpsTable_mix;
%             end
% 
%             if isChecked("Sensitivities")
%                 allsensTable = buildNumericBlock(allSensitivitiesRows, 'Sensitivities');
%                 allsensTable = renameAnalyteColumns(allsensTable, '_sens');
%                 if ismember('Data_Source', allsensTable.Properties.VariableNames)
%                     allsensTable.Data_Source = [];
%                 end
%                 tableBlocks{end+1} = allsensTable;
%             end
% 
%             if isChecked("LA parameters")
%                 %%%%%% Laser Parameters %%%%
%                 for i = 1:numel(allLaserParamTables)
%                     if isempty(allLaserParamTables{i}) || height(allLaserParamTables{i}) == 0
%                         nRows = height(allbkgdCpsRows_mat{i});
%                         % Find a non-empty table to copy column info and types
%                         for j = 1:numel(allLaserParamTables)
%                             if ~isempty(allLaserParamTables{j}) && height(allLaserParamTables{j}) > 0
%                                 refTable = allLaserParamTables{j};
%                                 varNames = refTable.Properties.VariableNames;
%                                 varTypes = varfun(@class, refTable, 'OutputFormat', 'cell');
%                                 emptyData = cell(1, numel(varNames));
%                                 for k = 1:numel(varNames)
%                                     switch varTypes{k}
%                                         case {'double', 'single'}
%                                             emptyData{k} = zeros(nRows,1);
%                                         case {'cell'}
%                                             emptyData{k} = cell(nRows,1);
%                                         case {'char', 'string'}
%                                             emptyData{k} = repmat({''}, nRows,1);
%                                         case {'datetime'}
%                                             emptyData{k} = NaT(nRows,1);
%                                         case {'logical'}
%                                             emptyData{k} = false(nRows,1);
%                                         otherwise
%                                             emptyData{k} = cell(nRows,1); % fallback
%                                     end
%                                 end
%                                 allLaserParamTables{i} = table(emptyData{:}, 'VariableNames', varNames);
%                                 break;
%                             end
%                         end
%                     end
%                 end
% 
%                % Harmonize columns across all laser parameter tables
%                 allLaserParamTables = harmonizeTableColumns(allLaserParamTables);
% 
%                 % Now you can safely concatenate
%                 allLaserParamTables = vertcat(allLaserParamTables{:});
%                 tableBlocks{end+1} = allLaserParamTables;
%             end
%             if isChecked("ICP-MS parameters")
%                 %%%%%% ICP-MS Parameters %%%%
% 
%                 % filepath: c:\Users\Sebastian\OneDrive - Universitaet Bern\PhD\Data\DataFrame\expandAndCombineICPMSParamTables.m
%                 for i = 1:numel(allICPMSParamTables)
%                     nRows = height(allbkgdCpsRows_mat{i});
%                     tbl = allICPMSParamTables{i};
%                     if isempty(tbl) || height(tbl) == 0
%                         % Find a non-empty reference table
%                         for j = 1:numel(allICPMSParamTables)
%                             if ~isempty(allICPMSParamTables{j}) && height(allICPMSParamTables{j}) > 0
%                                 refTable = allICPMSParamTables{j};
%                                 varNames = refTable.Properties.VariableNames;
%                                 emptyData = cell(1, numel(varNames));
%                                 for k = 1:numel(varNames)
%                                     refCol = refTable.(varNames{k});
%                                     refType = class(refCol);
%                                     if strcmp(refType, 'cell')
%                                         % Check the content type of the first non-empty cell
%                                         firstNonEmpty = find(~cellfun(@isempty, refCol), 1);
%                                         if isempty(firstNonEmpty)
%                                             % Default to empty string cell
%                                             emptyData{k} = repmat({''}, nRows, 1);
%                                         else
%                                             content = refCol{firstNonEmpty};
%                                             if ischar(content) || isstring(content)
%                                                 emptyData{k} = repmat({''}, nRows, 1);
%                                             elseif isnumeric(content)
%                                                 emptyData{k} = num2cell(zeros(nRows, 1));
%                                             else
%                                                 emptyData{k} = cell(nRows, 1);
%                                             end
%                                         end
%                                     elseif strcmp(refType, 'double') || strcmp(refType, 'single')
%                                         emptyData{k} = zeros(nRows, 1);
%                                     elseif strcmp(refType, 'char') || strcmp(refType, 'string')
%                                         emptyData{k} = repmat({''}, nRows, 1);
%                                     elseif strcmp(refType, 'datetime')
%                                         emptyData{k} = NaT(nRows, 1);
%                                     elseif strcmp(refType, 'logical')
%                                         emptyData{k} = false(nRows, 1);
%                                     else
%                                         emptyData{k} = cell(nRows, 1); % fallback
%                                     end
%                                 end
%                                 tbl = table(emptyData{:}, 'VariableNames', varNames);
%                                 break;
%                             end
%                         end
%                     elseif height(tbl) == 1 && nRows > 1
%                         tbl = tbl(ones(nRows,1), :);
%                     end
%                     allICPMSParamTables{i} = tbl;
%                 end
% 
%                 % Harmonize columns across all laser parameter tables
%                 allICPMSParamTables = harmonizeTableColumns(allICPMSParamTables);
% 
%                 allICPMSParamTables = vertcat(allICPMSParamTables{:});
% 
%                 tableBlocks{end+1} = allICPMSParamTables;
%             end
% 
%             % --- Concatenate only selected blocks ---
%             Base_Table = [tableBlocks{:}];
% 
%             % --- Normalize some textual columns to cellstr for downstream compatibility ---
%             if ~isempty(Base_Table)
%                 if ismember('Type', Base_Table.Properties.VariableNames)
%                     Base_Table.Type = normalizeToCellstr(Base_Table.Type);
%                 end
%                 if ismember('Material', Base_Table.Properties.VariableNames)
%                     Base_Table.Material = normalizeToCellstr(Base_Table.Material);
%                 end
%                 if ismember('Reference_Material', Base_Table.Properties.VariableNames)
%                     Base_Table.Reference_Material = normalizeToCellstr(Base_Table.Reference_Material);
%                 end
%                 if ismember('Quantified_with', Base_Table.Properties.VariableNames)
%                     Base_Table.Quantified_with = normalizeToCellstr(Base_Table.Quantified_with);
%                 end
%                 % Replace 0 with NaN in numeric columns only
%                 numericCols = varfun(@isnumeric, Base_Table, 'OutputFormat', 'uniform');
%                 if any(numericCols)
%                     X = Base_Table{:, numericCols};
%                     X(X == 0) = NaN;
%                     Base_Table{:, numericCols} = X;
%                 end
%             end
% 
%             % Replace 0 with NaN in LOD-filtered composition columns (cell arrays with mixed numeric/string)
%             lodFilteredSuffixes = {'_mat_ugg', '_incl_ugg', '_mix_ugg'};
%             for colIdx = 1:width(Base_Table)
%                 colName = Base_Table.Properties.VariableNames{colIdx};
%                 if any(contains(colName, lodFilteredSuffixes))
%                     col = Base_Table.(colName);
%                     if iscell(col)
%                         for rowIdx = 1:numel(col)
%                             if isnumeric(col{rowIdx}) && col{rowIdx} == 0
%                                 col{rowIdx} = NaN;
%                             end
%                         end
%                         Base_Table.(colName) = col;
%                     end
%                 end
%             end
% 
%             % Replace 0×0 double and 0 values in cell arrays with NaN
%             for colIdx = 1:width(Base_Table)
%                 col = Base_Table.(colIdx);
%                 if iscell(col)
%                     for rowIdx = 1:numel(col)
%                         cellVal = col{rowIdx};
%                         % Check if it's an empty double (0×0 double)
%                         if isnumeric(cellVal) && isempty(cellVal)
%                             col{rowIdx} = NaN;
%                             % Check if it's a 0 value
%                         elseif isnumeric(cellVal) && cellVal == 0
%                             col{rowIdx} = NaN;
%                         end
%                     end
%                     Base_Table{:, colIdx} = col;
%                 end
%             end
% 
%             % ----------------- Helpers -----------------
%             function val = getNestedValue(C, b, p, k, defaultVal)
%                 % Safely extracts C{b,p}{k} allowing various shapes and types
%                 val = defaultVal;
%                 try
%                     if iscell(C) && numel(C) >= (p-1)*size(C,1)+b && ~isempty(C)
%                         X = C{b,p};
%                         if iscell(X)
%                             if numel(X) >= k && ~isempty(X{k})
%                                 val = X{k};
%                             end
%                         elseif isstring(X) || ischar(X)
%                             if numel(X) >= k
%                                 val = X(k);
%                             end
%                         elseif isnumeric(X)
%                             if numel(X) >= k
%                                 val = X(k);
%                             end
%                         end
%                     end
%                 catch
%                     val = defaultVal;
%                 end
%             end
% 
%             function T = ensureAnalyteTable(T, analyteNames_, nRows_)
%                 % Ensure a table with nRows_ rows and analyte columns exists
%                 if ~istable(T) || isempty(T)
%                     T = table('Size', [nRows_, numel(analyteNames_)], ...
%                         'VariableTypes', repmat("double", 1, numel(analyteNames_)), ...
%                         'VariableNames', cellstr(analyteNames_));
%                     return
%                 end
%                 % Coerce non-double variables to double
%                 for v = 1:width(T)
%                     if ~isnumeric(T{:,v})
%                         try
%                             T{:,v} = double(T{:,v});
%                         catch
%                             T{:,v} = zeros(height(T),1);
%                         end
%                     end
%                 end
%                 % Fix height
%                 if height(T) ~= nRows_
%                     headers = T.Properties.VariableNames;
%                     TT = table('Size', [nRows_, numel(headers)], ...
%                         'VariableTypes', repmat("double", 1, numel(headers)), ...
%                         'VariableNames', headers);
%                     nCopy = min(height(T), nRows_);
%                     if nCopy > 0
%                         TT(1:nCopy, :) = T(1:nCopy, :);
%                     end
%                     T = TT;
%                 end
%             end
% 
%             function sortedHeaders = getSortedHeaders(rowsCell)
%                 if isempty(rowsCell)
%                     sortedHeaders = {};
%                     return
%                 end
%                 isT = cellfun(@istable, rowsCell);
%                 if ~any(isT)
%                     sortedHeaders = {};
%                     return
%                 end
%                 headersCell = cellfun(@(t) t.Properties.VariableNames, rowsCell(isT), 'UniformOutput', false);
%                 if isempty(headersCell)
%                     sortedHeaders = {};
%                     return
%                 end
%                 allHeaders = unique([headersCell{:}], 'stable');
%                 nums = cellfun(@localExtractNum, allHeaders);
%                 [~, sortIdx] = sort(nums); % NaNs go last
%                 sortedHeaders = allHeaders(sortIdx);
%             end
% 
%             function n = localExtractNum(h)
%                 tok = regexp(h, '\d+', 'match', 'once');
%                 if isempty(tok)
%                     n = NaN;
%                 else
%                     n = str2double(tok);
%                 end
%             end
% 
%             function tableWithAllColumns = addMissingColumns(inputTable, targetHeaders)
%                 if isempty(targetHeaders)
%                     tableWithAllColumns = inputTable;
%                     return
%                 end
%                 missingCols = setdiff(targetHeaders, inputTable.Properties.VariableNames, 'stable');
%                 if isempty(missingCols)
%                     tableWithAllColumns = inputTable(:, targetHeaders);
%                     return
%                 end
%                 missingData = zeros(height(inputTable), numel(missingCols));
%                 missingTable = array2table(missingData, 'VariableNames', missingCols);
%                 tableWithAllColumns = [inputTable missingTable];
%                 tableWithAllColumns = tableWithAllColumns(:, targetHeaders);
%             end
% 
%             function outTable = buildNumericBlock(rowsCell, label)
%                 if isempty(rowsCell)
%                     outTable = table();
%                     return
%                 end
%                 headers = getSortedHeaders(rowsCell);
%                 if ~isempty(rowsCell)
%                     rowsCell = cellfun(@(t) addMissingColumns(t, headers), rowsCell, 'UniformOutput', false);
%                 end
%                 if isempty(rowsCell)
%                     outTable = table();
%                     return
%                 end
%                 outTable = vertcat(rowsCell{:});
%                 if isempty(outTable)
%                     return
%                 end
%                 outTable = addvars(outTable, repmat({label}, height(outTable), 1), ...
%                     'Before', 1, 'NewVariableNames', 'Data_Source');
%             end
% 
%             function T = renameAnalyteColumns(T, suffix)
%                 if isempty(T)
%                     return
%                 end
%                 metaCols = {'Data_Source'};
%                 for v = 1:width(T)
%                     if ~ismember(T.Properties.VariableNames{v}, metaCols)
%                         T.Properties.VariableNames{v} = [T.Properties.VariableNames{v} suffix];
%                     end
%                 end
%             end
% 
%             function T = normalizeToCellstr(T)
%                 if istable(T) || isstruct(T)
%                     return
%                 end
%                 if iscell(T)
%                     try
%                         T = cellfun(@char, T, 'UniformOutput', false);
%                     catch
%                         T = cellfun(@(x) char(string(x)), T, 'UniformOutput', false);
%                     end
%                 else
%                     T = cellstr(string(T));
%                 end
%             end
% 
%             function C = getNestedField(spotStruct, fieldName, uniqueBracketIds, unique_CALs, nSpots, defaultVal)
%                 if isfield(spotStruct, fieldName)
%                     C = spotStruct.(fieldName);
%                 else
%                     C = cell(numel(uniqueBracketIds), numel(unique_CALs));
%                     for b = 1:numel(uniqueBracketIds)
%                         for p = 1:numel(unique_CALs)
%                             if ischar(defaultVal) || isstring(defaultVal)
%                                 C{b, p} = repmat({defaultVal}, nSpots, 1);
%                             else
%                                 C{b, p} = repmat(defaultVal, nSpots, 1);
%                             end
%                         end
%                     end
%                 end
%             end
% 
%             function C = getNestedTable(spotStruct, fieldName, uniqueBracketIds, unique_CALs, nSpots, analyteNames)
%                 if isfield(spotStruct, fieldName)
%                     C = spotStruct.(fieldName);
%                 else
%                     C = cell(numel(uniqueBracketIds), numel(unique_CALs));
%                     for b = 1:numel(uniqueBracketIds)
%                         for p = 1:numel(unique_CALs)
%                             C{b, p} = table('Size', [nSpots, numel(analyteNames)], ...
%                                 'VariableTypes', repmat("double", 1, numel(analyteNames)), ...
%                                 'VariableNames', cellstr(analyteNames));
%                         end
%                     end
%                 end
%             end
% 
%             function C = getNestedMixingRatio(spotStruct, fieldName, uniqueBracketIds, unique_CALs, nSpots)
%                 if isfield(spotStruct, fieldName)
%                     C = spotStruct.(fieldName);
%                 else
%                     % Assume 3 mixing ratios per bracket/CAL, fill with NaN
%                     C = cell(numel(uniqueBracketIds), numel(unique_CALs));
%                     for b = 1:numel(uniqueBracketIds)
%                         for p = 1:numel(unique_CALs)
%                             C{b, p} = repmat({NaN}, nSpots, 1);
%                         end
%                     end
%                 end
%             end
% 
%             function val = getNestedMixingRatioValue(C, b, p, k)
%                 % Returns a cell array of mixing ratios for spot k, bracket b, CAL p
%                 val = [];
%                 try
%                     if iscell(C) && numel(C) >= (p-1)*size(C,1)+b && ~isempty(C)
%                         X = C{b,p};
%                         if iscell(X)
%                             if numel(X) >= k && ~isempty(X{k})
%                                 val = X{k};
%                             else
%                                 val = NaN;
%                             end
%                         elseif isnumeric(X)
%                             if numel(X) >= k
%                                 val = X(k);
%                             else
%                                 val = NaN;
%                             end
%                         end
%                     end
%                 catch
%                     val = NaN;
%                 end
%             end
% 
%             function T = struct_to_table(S)
%                 % Converts a struct S with fields of arrays/cells/chars to a table.
%                 % Singleton fields are duplicated to match the length of the longest field.
%                 if isempty(S)
%                     T = table();
%                     return
%                 end
%                 fields = fieldnames(S);
%                 lens = zeros(numel(fields),1);
%                 for o = 1:numel(fields)
%                     col = S.(fields{o});
%                     if ischar(col) || (isstring(col) && isscalar(col))
%                         lens(o) = 1;
%                     else
%                         lens(o) = numel(col);
%                     end
%                 end
%                 n = max(lens);
%                 T = table();
%                 for o = 1:numel(fields)
%                     col = S.(fields{o});
%                     % Handle empty fields
%                     if isempty(col)
%                         % Guess type from field name or just use NaN
%                         col = nan(n,1);
%                         % Handle char arrays and string scalars
%                     elseif ischar(col) || (isstring(col) && isscalar(col))
%                         col = repmat({col}, n, 1);
%                     elseif iscell(col) && numel(col) == 1 && n > 1
%                         col = repmat(col, n, 1);
%                     elseif isnumeric(col) && numel(col) == 1 && n > 1
%                         col = repmat(col, n, 1);
%                     elseif numel(col) ~= n
%                         error('Field %s has %d elements, expected %d.', fields{o}, numel(col), n);
%                     end
%                     % Ensure column is column vector
%                     if isrow(col) && ~ischar(col)
%                         col = col';
%                     end
%                     T.(fields{o}) = col;
%                 end
%             end
% 
%             function harmonizedTables = harmonizeTableColumns(tableCell)
%                 % Collects all unique column names across all tables
%                 % and ensures each table has all columns (filling missing with NaN)
% 
%                 if isempty(tableCell)
%                     harmonizedTables = tableCell;
%                     return
%                 end
% 
%                 % Collect all unique variable names
%                 allVarNames = {};
%                 for i = 1:numel(tableCell)
%                     if ~isempty(tableCell{i}) && istable(tableCell{i})
%                         allVarNames = [allVarNames, tableCell{i}.Properties.VariableNames];
%                     end
%                 end
%                 allVarNames = unique(allVarNames, 'stable');
% 
%                 % Add missing columns to each table
%                 for i = 1:numel(tableCell)
%                     tbl = tableCell{i};
%                     if isempty(tbl) || ~istable(tbl)
%                         continue
%                     end
% 
%                     existingVars = tbl.Properties.VariableNames;
%                     missingVars = setdiff(allVarNames, existingVars, 'stable');
% 
%                     for j = 1:numel(missingVars)
%                         % Add missing column filled with NaN or empty cells
%                         varName = missingVars{j};
%                         nRows = height(tbl);
% 
%                         % Try to infer type from other tables
%                         varType = 'double'; % default
%                         for k = 1:numel(tableCell)
%                             if ~isempty(tableCell{k}) && istable(tableCell{k}) && ...
%                                     ismember(varName, tableCell{k}.Properties.VariableNames)
%                                 refCol = tableCell{k}.(varName);
%                                 varType = class(refCol);
%                                 break;
%                             end
%                         end
% 
%                         % Create appropriate empty column
%                         switch varType
%                             case {'double', 'single'}
%                                 tbl.(varName) = nan(nRows, 1);
%                             case 'cell'
%                                 tbl.(varName) = cell(nRows, 1);
%                             case {'char', 'string'}
%                                 tbl.(varName) = repmat({''}, nRows, 1);
%                             case 'datetime'
%                                 tbl.(varName) = NaT(nRows, 1);
%                             case 'logical'
%                                 tbl.(varName) = false(nRows, 1);
%                             otherwise
%                                 tbl.(varName) = cell(nRows, 1);
%                         end
%                     end
% 
%                     % Reorder columns to match allVarNames
%                     tbl = tbl(:, allVarNames);
%                     tableCell{i} = tbl;
%                 end
% 
%                 harmonizedTables = tableCell;
%             end
% 
%             function filteredCompTable = applyLODFiltering(compTable, lodTable, compSuffix, lodSuffix)
%                 % Apply LOD filtering: replace values below LOD with formatted strings like '<0.123'
% 
%                 filteredCompTable = compTable;
% 
%                 % Get composition and LOD column names (excluding metadata)
%                 compCols = compTable.Properties.VariableNames;
%                 compCols = compCols(contains(compCols, compSuffix));
% 
%                 lodCols = lodTable.Properties.VariableNames;
%                 lodCols = lodCols(contains(lodCols, lodSuffix));
% 
%                 % Match corresponding columns
%                 for l = 1:numel(compCols)
%                     % Extract element name
%                     elementName = strrep(compCols{l}, compSuffix, '');
%                     lodColName = [elementName, lodSuffix];
% 
%                     if ismember(lodColName, lodCols)
%                         % Get arrays
%                         comp_array = compTable.(compCols{l});
%                         lod_array = lodTable.(lodColName);
% 
%                         % Create logical mask for values below detection limit
%                         below_lod = comp_array < lod_array;
% 
%                         % Create cell array for display values
%                         display_values = num2cell(comp_array);
% 
%                         % Format detection limit values where needed
%                         display_values(below_lod) = arrayfun(@(x) ['<' num2str(x)], ...
%                             lod_array(below_lod), 'UniformOutput', false);
% 
%                         % Update filtered table
%                         filteredCompTable.(compCols{l}) = display_values;
%                     end
%                 end
%             end
% 
%         end
%  


    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, source)
            app.sourceapp = source;
            app.base = source.base;

            update_project_session_tree(app)


        end

        % Button pushed function: ExportcsvTableButton
        function ExportcsvTableButtonPushed(app, event)
            

            progress = uiprogressdlg(app.TableBuilderUIFigure, 'Title', 'ICP-Base', ...
                'Message', 'Building and exporting table...', 'Cancelable', 'off', 'Indeterminate','on');

            try
                Base_Table = build_assigned_table_from_base(app);


                if app.MajorsasoxideswtCheckBox.Value
                    Base_Table = convert_majors_to_oxides_base_table(app, Base_Table);
                end

                if app.ExcludeCalibratorsCheckBox.Value
                    Base_Table = exclude_CALs(app, Base_Table);
                end


            catch

                message = "Table building failed";
                uialert(app.TableBuilderUIFigure,message,"Warning", ...
                    "Icon","warning");
            end

            if isempty(Base_Table)
                message = "Exporting failed. No table was built.";
                uialert(app.TableBuilderUIFigure,message,"Warning", ...
                    "Icon","warning");
                return
            end


            [filename, pathname] = uiputfile('*.csv', 'Save .csv file');
            outputpath = fullfile(pathname, filename);
            if isequal(filename, 0) || isequal(pathname, 0)
                return; % User canceled file selection
            end

            % Write the table to Excel
            writetable(Base_Table, outputpath);

            delete(progress)
        end

        % Button pushed function: ProceedtoVisModuleButton
        function ProceedtoVisModuleButtonPushed(app, event)

            progress = uiprogressdlg(app.TableBuilderUIFigure, 'Title', 'ICP-Base', ...
                'Message', 'Building table...', 'Cancelable', 'off', 'Indeterminate','on');

            Base_Table = build_assigned_table_from_base(app);


            if app.MajorsasoxideswtCheckBox.Value
                Base_Table = convert_majors_to_oxides_base_table(app, Base_Table);
            end

            if app.ExcludeCalibratorsCheckBox.Value
                Base_Table = exclude_CALs(app, Base_Table);
            end

            if isempty(Base_Table)
                message = "Proceeding to the VisModule failed. No table was built.";
                uialert(app.TableBuilderUIFigure,message,"Warning", ...
                    "Icon","warning");
                return
            end

            VisModule(Base_Table, app.sourceapp);
            delete(progress)
        end

        % Callback function: ProjectSessionTree
        function ProjectSessionTreeCheckedNodesChanged(app, event)

            SelectionModeDropDownValueChanged(app)

            %update_spot_meta_tree(app)
        end

        % Value changed function: SelectionModeDropDown
        function SelectionModeDropDownValueChanged(app, event)
            value = app.SelectionModeDropDown.Value;

            if isequal(value, 'Simple')

                app.SimplePanel.Visible = true;
                app.AdvancedPanel.Visible = false;

                update_spot_meta_tree(app)

                % Find and check the Matrix/Host mass fractions node
                nodesToCheck = [];

                if ~isempty(app.SpotMetaTree.Children)
                    for i = 1:numel(app.SpotMetaTree.Children)
                        node = app.SpotMetaTree.Children(i);
                        % Check if this is the Quantification node
                        if strcmp(node.Text, "Quantification")
                            % Loop through its children
                            for j = 1:numel(node.Children)
                                childNode = node.Children(j);
                                if strcmp(childNode.Text, "Matrix/Host mass fractions")
                                    nodesToCheck = [nodesToCheck; childNode];
                                elseif strcmp(childNode.Text, "Inclusion mass fractions")
                                    nodesToCheck = [nodesToCheck; childNode];
                                end
                            end
                            break;
                        end
                    end
                end

                % Set all found nodes as checked
                if ~isempty(nodesToCheck)
                    app.SpotMetaTree.CheckedNodes = nodesToCheck;
                end


            elseif isequal(value, 'Advanced')
                
                app.SimplePanel.Visible = false;
                app.AdvancedPanel.Visible = true;

                update_spot_meta_tree(app)
            end

        end

        % Menu selected function: ExportasmatMenu
        function ExportasmatMenuSelected(app, event)
            
            progress = uiprogressdlg(app.TableBuilderUIFigure, 'Title', 'ICP-Base', ...
                'Message', 'Building and exporting table...', 'Cancelable', 'off', 'Indeterminate','on');

            try
                Base_Table = build_assigned_table_from_base(app);


                if app.MajorsasoxideswtCheckBox.Value
                    Base_Table = convert_majors_to_oxides_base_table(app, Base_Table);
                end

                if app.ExcludeCalibratorsCheckBox.Value
                    Base_Table = exclude_CALs(app, Base_Table);
                end

            catch

                message = "Table building failed";
                uialert(app.TableBuilderUIFigure,message,"Warning", ...
                    "Icon","warning");
            end

            if isempty(Base_Table)
                message = "Exporting failed. No table was built.";
                uialert(app.TableBuilderUIFigure,message,"Warning", ...
                    "Icon","warning");
                return
            end


            [filename, pathname] = uiputfile('*.mat', 'Save .mat file');
            outputpath = fullfile(pathname, filename);
            if isequal(filename, 0) || isequal(pathname, 0)
                return; % User canceled file selection
            end

            % Write the table
            save(outputpath, 'Base_Table');

            delete(progress)
        end

        % Value changed function: RemovemassesfromelementnameCheckBox
        function RemovemassesfromelementnameCheckBoxValueChanged(app, event)

            if app.RemovemassesfromelementnameCheckBox.Value == 0
                message = "Be aware that the quantified results are not isotope-resolved! For example, ‘Pb206’ indicates that Pb was measured on mass 206 a.m.u., not that a specific amount of the isotope ²⁰⁶Pb (in e.g., µg/g) is present in the sample.";
                uialert(app.TableBuilderUIFigure,message,"Warning", ...
                    "Icon","warning");
            end

        end

        % Value changed function: MajorsasoxideswtCheckBox
        function MajorsasoxideswtCheckBoxValueChanged(app, event)
            value = app.MajorsasoxideswtCheckBox.Value;


        end

        % Callback function
        function devButtonPushed(app, event)
            
        Base_Table = build_assigned_table_from_base(app);


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.TableBuilderUIFigure = uifigure('Visible', 'off');
            app.TableBuilderUIFigure.Name = 'Table Builder';
            app.TableBuilderUIFigure.Icon = 'icon_48.png';
            app.TabGroup = uitabgroup(app.TableBuilderUIFigure);
            app.DataselectionTab = uitab(app.TabGroup);
            app.DataselectionTab.Title = 'Data selection';
            app.ProjectSessionTree = uitree(app.DataselectionTab, 'checkbox');
            % Assign Checked Nodes
            app.ProjectSessionTree.CheckedNodesChangedFcn = createCallbackFcn(app, @ProjectSessionTreeCheckedNodesChanged, true);
            app.ExportcsvTableButton = uibutton(app.DataselectionTab, 'push');
            app.ExportcsvTableButton.ButtonPushedFcn = createCallbackFcn(app, @ExportcsvTableButtonPushed, true);
            app.ExportcsvTableButton.Text = 'Export .csv Table';
            app.ProceedtoVisModuleButton = uibutton(app.DataselectionTab, 'push');
            app.ProceedtoVisModuleButton.ButtonPushedFcn = createCallbackFcn(app, @ProceedtoVisModuleButtonPushed, true);
            app.ProceedtoVisModuleButton.Text = 'Proceed to VisModule';
            app.SelectionModeDropDownLabel = uilabel(app.DataselectionTab);
            app.SelectionModeDropDownLabel.Text = 'Selection Mode:';
            app.SelectionModeDropDown = uidropdown(app.DataselectionTab);
            app.SelectionModeDropDown.Items = {'Simple', 'Advanced'};
            app.SelectionModeDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectionModeDropDownValueChanged, true);
            app.SelectionModeDropDown.Value = 'Simple';
            app.AdvancedPanel = uipanel(app.DataselectionTab);
            app.SpotMetaTree = uitree(app.AdvancedPanel, 'checkbox');
            app.SimplePanel = uipanel(app.DataselectionTab);
            app.IfyouexportthetableorproceedtotheVisModulewithLabel = uilabel(app.SimplePanel);
            app.IfyouexportthetableorproceedtotheVisModulewithLabel.Text = {'Exporting the table or proceeding to the VisModule with Simple selection mode (currently active) will include all LOD filtered mass fraction data, reported in µg/g except for oxides, which are expressed as wt%.'; ''; 'To include CPS data, limits of detection, sensitivities, or LA/ICP-MS metadata in the table, switch the selection mode to Advanced.'};
            app.RemovemassesfromelementnameCheckBox = uicheckbox(app.DataselectionTab);
            app.RemovemassesfromelementnameCheckBox.ValueChangedFcn = createCallbackFcn(app, @RemovemassesfromelementnameCheckBoxValueChanged, true);
            app.RemovemassesfromelementnameCheckBox.Text = 'Remove masses from element name';
            app.RemovemassesfromelementnameCheckBox.Value = true;
            app.MajorsasoxideswtCheckBox = uicheckbox(app.DataselectionTab);
            app.MajorsasoxideswtCheckBox.ValueChangedFcn = createCallbackFcn(app, @MajorsasoxideswtCheckBoxValueChanged, true);
            app.MajorsasoxideswtCheckBox.Text = 'Majors as oxides (wt%)';
            app.ExcludeCalibratorsCheckBox = uicheckbox(app.DataselectionTab);
            app.ExcludeCalibratorsCheckBox.Text = 'Exclude Calibrators';
            app.ExcludeCalibratorsCheckBox.Value = true;
            app.ContextMenu = uicontextmenu(app.TableBuilderUIFigure);
            app.ExportasmatMenu = uimenu(app.ContextMenu);
            app.ExportasmatMenu.MenuSelectedFcn = createCallbackFcn(app, @ExportasmatMenuSelected, true);
            app.ExportasmatMenu.Text = 'Export as .mat';
            % Assign app.ContextMenu
            app.ExportcsvTableButton.ContextMenu = app.ContextMenu;
            % Show the figure after all components are created
            app.TableBuilderUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Table_builder_Submodule(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.TableBuilderUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.TableBuilderUIFigure)
        end
    end
end