classdef Table_builder_Submodule < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        TableBuilderUIFigure        matlab.ui.Figure
        TabGroup                    matlab.ui.container.TabGroup
        DataselectionTab            matlab.ui.container.Tab
        SimplePanel                 matlab.ui.container.Panel
        SimpleDropDown              matlab.ui.control.DropDown
        AdvancedPanel               matlab.ui.container.Panel
        SpotMetaTree                matlab.ui.container.CheckBoxTree
        SelectionModeDropDown       matlab.ui.control.DropDown
        SelectionModeDropDownLabel  matlab.ui.control.Label
        ProceedtoVisModuleButton    matlab.ui.control.Button
        ExportTableButton           matlab.ui.control.Button
        ProjectSessionTree          matlab.ui.container.CheckBoxTree
    end


    properties (Access = public)
        base
    end


    methods (Access = private)

        %%%%% GUI functions %%%%%%

        function ResetTree(app,Tree2Update)
            if not(exist('Tree2Update','var'))
                Tree2Update=app.ProjectSessionTree;
            end

            %             if ~isempty(Tree2Update.SelectedNodes)
            %                 app.SelectedNodes_MainTree = Tree2Update.SelectedNodes.NodeData;
            %             end

            a = Tree2Update.Children;
            a.delete;
        end

        function update_project_session_tree(app)

            Tree2Update=app.ProjectSessionTree;

            ResetTree(app,app.ProjectSessionTree)

            base_struct = app.base;

            if ~isempty(base_struct)
                for i = 1:numel(base_struct)
                    % Top level: Project name
                    projectName = base_struct(i).project_name;
                    p0 = uitreenode(Tree2Update, 'Text', string(projectName)); %, 'NodeData', []

                    % Check if there are projects under this base
                    projects = base_struct(i).project;
                    if ~isempty(projects)
                        for j = 1:numel(projects)
                            % Check if 'session' field exists and is not empty
                            if isfield(projects(j), 'session') && ~isempty(projects(j).session)
                                sessions = projects(j).session;
                                for k = 1:numel(sessions)
                                    sessionName = sessions(k).session_name;
                                    p1 = uitreenode(p0, 'Text', string(sessionName), 'NodeData', [i, j]);
                                    % Add more levels here if needed
                                end
                            end
                        end
                    end
                end
            end


        end

        function update_spot_meta_tree(app)

            Tree2Update=app.SpotMetaTree;

            ResetTree(app, app.SpotMetaTree);
            Tree2Update = app.SpotMetaTree;

            % Get checked nodes from your project/session tree
            checkedNodes = app.ProjectSessionTree.CheckedNodes; % adjust name if needed

            if isempty(checkedNodes)
                return
            end

            %%%%%%%%%%%%%%%%%%% Metadata Node %%%%%%%%%%%%%%%%%%%%

            % Top-level nodes
            metaNode = uitreenode(Tree2Update, 'Text', "Metadata", 'NodeData', "Metadata");

            hasICP = false;
            hasLA = false;

            % Loop through checked sessions
            for n = 1:numel(checkedNodes)
                nodeData = checkedNodes(n).NodeData;
                if isempty(nodeData)
                    continue
                end
                if numel(nodeData) == 2 && nodeData(2) > 0
                    j = nodeData(1); k = nodeData(2);
                    session = app.base(j).project(k).session;
                    if isfield(session, 'meta')
                        if isfield(session.meta, 'icp_ms_parameters')
                            hasICP = true;
                        end
                        if isfield(session.meta, 'laser_parameters')
                            hasLA = true;
                        end
                    end
                end
            end

            if hasLA
                uitreenode(metaNode, 'Text', "LA parameters", 'NodeData', "LA parameters");
            end
            if hasICP
                uitreenode(metaNode, 'Text', "ICP-MS parameters", 'NodeData', "ICP-MS parameters");
            end

            %%%%%%%%%%%%%%%%%%% Quantification Node %%%%%%%%%%%%%%%%%%%%
            quantNode = uitreenode(Tree2Update, 'Text', "Quantification", 'NodeData', "Quantification");

            % Standard quantification children
            quantLabels = [...
                "Internal standardization", ...
                "Matrix/Host counts-per-sweep", ...
                "Sensitivities", ...
                "Matrix/Host mass fractions", ...
                "Matrix/Host limits of detection" ...
                ];
            for q = 1:numel(quantLabels)
                uitreenode(quantNode, 'Text', quantLabels(q), 'NodeData', quantLabels(q));
            end

            % --- Only add Inclusion nodes if any checked session is Inclusion ---
            addInclusion = false;

            for n = 1:numel(checkedNodes)

                nodeData = checkedNodes(n).NodeData; % [j, k] indices for project, session
                if isempty(nodeData)
                    continue
                end
                if numel(nodeData) == 2 && nodeData(2) > 0
                    j = nodeData(1); k = nodeData(2);
                    session = app.base(j).project(k).session;
                    if isfield(session, 'config') && isfield(session.config, 'reductiontype') ...
                            && strcmp(session.config.reductiontype, "Inclusion")
                        addInclusion = true;
                        break;
                    end
                end
            end

            if addInclusion
                inclusionLabels = [...
                    "Inclusion counts-per-sweep", ...
                    "Inclusion mass fractions", ...
                    "Inclusion limits of detection", ...
                    "Mix counts-per-sweep", ...
                    "Mix mass fractions", ...
                    "Host-Inclusion-unmixing" ...
                    ];
                for q = 1:numel(inclusionLabels)
                    uitreenode(quantNode, 'Text', inclusionLabels(q), 'NodeData', inclusionLabels(q));
                end
            end

        end


        %%%%% Table building functions %%%%%%


        % without the treenodes
        %         function Base_Table = build_table_from_base_v1(app)
        %
        %             % basic variables
        %             allProjects = {};
        %             allSessions = {};
        %             allSpotnames = {};
        %             alldates = {};
        %             allSamples = {};
        %             allMaterials = {};
        %
        %             % Internal standardization
        %             allTypes = {};
        %             allRefMaterials = {};
        %             allIntStdElements = {};
        %             allIntStdValues = {};
        %             allIntStdOxValues = {};
        %             allFeRatios = {};
        %             allIntTimePoints = {};
        %             allQuantifiedWith = {};
        %             allusedBracket_id = {};
        %             allIntStdElements_Incl = {};
        %             allIntStdValues_Incl = {};
        %             allSecondIntStdElements_Incl = {};
        %             allSecondIntStdValues_Incl = {};
        %             allIntStdOxValues_Incl = {};
        %             allFeRatios_Incl = {};
        %
        %             % Host-Inclusion-unmixing
        %             allUnmixingVia = {};
        %             allMatrixOnlyTracer = {};
        %             allMatrixFromSpot = {};
        %             allMixingRatios = {};
        %
        %             % Sensitivities
        %             allSensitivitiesRows = {};
        %             % Matrix/Host mass fractions
        %             allmatrixcompRows = {};
        %             % Inclusion mass fractions
        %             allinclcompRows = {};
        %             % Matrix/Host limits of detection
        %             allmatrixlodRows = {};
        %             % Inclusion limits of detection
        %             allincllodRows = {};
        %             % Mix mass fractions
        %             allmixcompRows = {};
        %
        %             % Matrix/Host counts-per-sweep
        %             allbkgdCpsRows_mat = {};
        %             % Inclusion counts-per-sweep
        %             allbkgdCpsRows_incl = {};
        %             % Mix counts-per-sweep
        %             allbkgdCpsRows_mix = {};
        %
        %             % LA parameters
        %             allLaserParamTables = {};
        %             % ICP-MS parameters
        %             allICPMSParamTables = {};
        %
        %             % Get checked nodes from the tree
        %             checkedNodes = app.ProjectSessionTree.CheckedNodes;
        %             checkedIndices = [];
        %             for n = 1:numel(checkedNodes)
        %                 nodeData = checkedNodes(n).NodeData;
        %                 if ~isempty(nodeData) && numel(nodeData) == 2 && nodeData(2) > 0
        %                     checkedIndices = [checkedIndices; nodeData];
        %                 end
        %             end
        %
        %             for i = 1:numel(app.base)
        %
        %                 projectName = app.base(i).project_name;
        %
        %                 for j = 1:numel(app.base(i).project)
        %
        %                     % Only process if this [i, j] is in checkedIndices
        %                     if isempty(checkedIndices) || ~ismember([i, j], checkedIndices, 'rows')
        %                         continue
        %                     end
        %
        %                     if isfield(app.base(i).project(j), 'session')
        %                         session = app.base(i).project(j).session;
        %                     else
        %                         continue
        %                     end
        %
        %                     % Session meta
        %                     if isfield(session, 'session_name') && ~isempty(session.session_name)
        %                         sessionName = char(session.session_name);
        %                     else
        %                         sessionName = '';
        %                     end
        %
        %                     if isfield(session, 'meta') && isfield(session.meta, 'date') && ~isempty(session.meta.date)
        %                         sessiondate = session.meta.date;
        %                     else
        %                         sessiondate = '';
        %                     end
        %
        %                     % Spot block
        %                     if ~isfield(session, 'spot') || isempty(session.spot)
        %                         continue
        %                     end
        %
        %                     spotnames = session.spot.name;
        %                     nSpots = numel(spotnames);
        %
        %                     % Sample, material
        %                     if isfield(session.meta, 'sample') && ~isempty(session.meta.sample)
        %                         sample = session.meta.sample(:);
        %                     else
        %                         sample = repmat({''}, nSpots, 1);
        %                     end
        %
        %                     if isfield(session.meta, 'material') && ~isempty(session.meta.material)
        %                         material = session.meta.material(:);
        %                     else
        %                         material = repmat({''}, nSpots, 1);
        %                     end
        %
        %                     % Type and PRMs
        %                     if isfield(session.spot, 'type') && ~isempty(session.spot.type)
        %                         type = string(session.spot.type(:));
        %                     else
        %                         type = strings(nSpots, 1);
        %                     end
        %
        %                     % Reference material
        %                     if isfield(session.spot, 'referencematerial') && ~isempty(session.spot.referencematerial)
        %                         referencematerial = string(session.spot.referencematerial(:));
        %                     else
        %                         referencematerial = strings(nSpots, 1);
        %                     end
        %
        %                     % Determine PRMs from type == "PRM"
        %                     prm_indices = (type == "PRM");
        %                     if any(prm_indices)
        %                         prm_materials = referencematerial(prm_indices);
        %                         unique_PRMs = unique(prm_materials);
        %                     else
        %                         unique_PRMs = string.empty(0,1);
        %                     end
        %
        %                     % Bracket IDs
        %                     if isfield(session.spot, 'bracket_id') && ~isempty(session.spot.bracket_id)
        %                         bracket_id = session.spot.bracket_id(:);
        %                         if ~isnumeric(bracket_id)
        %                             bracket_id = zeros(nSpots,1);
        %                         end
        %                         uniqueBracketIds = unique(bracket_id);
        %                         uniqueBracketIds = uniqueBracketIds(uniqueBracketIds ~= 0);
        %                     else
        %                         bracket_id = zeros(nSpots, 1); % 0 marks missing
        %                         uniqueBracketIds = [];
        %                     end
        %
        %                     % Integer time points
        %                     if isfield(session.spot, 'integer_time_points') && ~isempty(session.spot.integer_time_points)
        %                         integer_time_points = session.spot.integer_time_points(:);
        %                         if ~isnumeric(integer_time_points)
        %                             integer_time_points = NaN(nSpots,1);
        %                         end
        %                     else
        %                         integer_time_points = NaN(nSpots, 1);
        %                     end
        %
        %                     % Analyte names
        %                     if isfield(session.spot, 'analytes') && ~isempty(session.spot.analytes)
        %                         analyteNames = strtrim(string(session.spot.analytes(:)));
        %                     else
        %                         analyteNames = strings(0,1);
        %                     end
        %
        %                     %
        %                     if isfield(session.spot, 'unmixing_via') && ~isempty(session.spot.unmixing_via)
        %                         unmixing_via = string(session.spot.unmixing_via(:));
        %                     else
        %                         unmixing_via =repmat({''}, nSpots, 1);
        %                     end
        %
        %                     if isfield(session.spot, 'matrix_only_tracer') && ~isempty(session.spot.matrix_only_tracer)
        %                         matrix_only_tracer = string(session.spot.matrix_only_tracer(:));
        %                     else
        %                         matrix_only_tracer =repmat({''}, nSpots, 1);
        %                     end
        %
        %                     if isfield(session.spot, 'matrix_from_spot') && ~isempty(session.spot.matrix_from_spot)
        %                         matrix_from_spot = string(session.spot.matrix_from_spot(:));
        %                     else
        %                         matrix_from_spot =repmat({''}, nSpots, 1);
        %                     end
        %
        %                     if isfield(app.base(i).project(j).session.spot, 'bkgd_corrected_cps')
        %                         bkgdCps_mat = app.base(i).project(j).session.spot.bkgd_corrected_cps;
        %                     end
        %
        %                     if isfield(app.base(i).project(j).session.spot, 'bkgd_corrected_cps_incl')
        %                         bkgdCps_incl = app.base(i).project(j).session.spot.bkgd_corrected_cps_incl;
        %                     else
        %                         bkgdCps_incl = array2table(zeros(height(bkgdCps_mat), width(bkgdCps_mat)), ...
        %                             'VariableNames', bkgdCps_mat.Properties.VariableNames);
        %                     end
        %
        %                     if isfield(app.base(i).project(j).session.spot, 'bkgd_corrected_cps_mix')
        %                         bkgdCps_mix = app.base(i).project(j).session.spot.bkgd_corrected_cps_mix;
        %                     else
        %                         bkgdCps_mix = array2table(zeros(height(bkgdCps_mat), width(bkgdCps_mat)), ...
        %                             'VariableNames', bkgdCps_mat.Properties.VariableNames);
        %                     end
        %
        %                     if isfield(app.base(i).project(j).session.spot, 'prm_sensitivities')
        %                         prm_sensitivities = app.base(i).project(j).session.spot.prm_sensitivities;
        %                     end
        %
        %                     % --- Laser parameters integration ---
        %                     laserParamTable = table();
        %                     if isfield(session, 'meta') && isfield(session.meta, 'laser_parameters')
        %                         laserParamStruct = session.meta.laser_parameters;
        %                         laserParamTable = struct_to_table(laserParamStruct);
        %                     else
        %                         laserParamTable = table();
        %                     end
        %                     % --- End laser parameters integration ---
        %
        %                     ICPMSParamTable = table();
        %                     if isfield(session, 'meta') && isfield(session.meta, 'icp_ms_parameters')
        %                         ICPMSParamStruct = session.meta.icp_ms_parameters;
        %                         ICPMSParamTable = struct_to_table(ICPMSParamStruct);
        %                     else
        %                         ICPMSParamTable = table();
        %                     end
        %
        %                     % Internal standard elements (strings)
        %                     internal_standard_element = getNestedField(session.spot, 'internal_standard', uniqueBracketIds, unique_PRMs, nSpots, "");
        %                     internal_standard_value = getNestedField(session.spot, 'internal_standard_mass_fraction', uniqueBracketIds, unique_PRMs, nSpots, NaN);
        %                     internal_standard_value_ox = getNestedField(session.spot, 'internal_standard_oxide_wt', uniqueBracketIds, unique_PRMs, nSpots, NaN);
        %                     Fe_ratio_for_total_oxide = getNestedField(session.spot, 'Fe_ratio_for_total_oxide', uniqueBracketIds, unique_PRMs, nSpots, NaN);
        %
        %                     % sensitivities
        %                     sensitivities = getNestedTable(session.spot, 'sensitivities', uniqueBracketIds, unique_PRMs, nSpots, analyteNames);
        %
        %                     % compositions and lods
        %                     matrix_composition = getNestedTable(session.spot, 'matrix_composition', uniqueBracketIds, unique_PRMs, nSpots, analyteNames);
        %                     matrix_limits_of_detection = getNestedTable(session.spot, 'matrix_limits_of_detection', uniqueBracketIds, unique_PRMs, nSpots, analyteNames);
        %
        %                     % Inclusion internal standard elements and related fields
        %                     internal_standard_inclusion = getNestedField(session.spot, 'internal_standard_inclusion', uniqueBracketIds, unique_PRMs, nSpots, "");
        %                     internal_standard_mass_fraction_inclusion = getNestedField(session.spot, 'internal_standard_mass_fraction_inclusion', uniqueBracketIds, unique_PRMs, nSpots, NaN);
        %                     second_internal_standard_inclusion = getNestedField(session.spot, 'second_internal_standard_inclusion', uniqueBracketIds, unique_PRMs, nSpots, "");
        %                     second_internal_standard_mass_fraction_inclusion = getNestedField(session.spot, 'second_internal_standard_mass_fraction_inclusion', uniqueBracketIds, unique_PRMs, nSpots, NaN);
        %                     internal_standard_oxide_wt_inclusion = getNestedField(session.spot, 'internal_standard_oxide_wt_inclusion', uniqueBracketIds, unique_PRMs, nSpots, NaN);
        %                     Fe_ratio_for_total_oxide_inclusion = getNestedField(session.spot, 'Fe_ratio_for_total_oxide_inclusion', uniqueBracketIds, unique_PRMs, nSpots, NaN);
        %
        %                     inclusion_composition = getNestedTable(session.spot, 'inclusion_composition', uniqueBracketIds, unique_PRMs, nSpots, analyteNames);
        %                     inclusion_limits_of_detection = getNestedTable(session.spot, 'inclusion_limits_of_detection', uniqueBracketIds, unique_PRMs, nSpots, analyteNames);
        %                     mix_composition = getNestedTable(session.spot, 'mix_composition', uniqueBracketIds, unique_PRMs, nSpots, analyteNames);
        %
        %                     % Mixing ratios (cell array of cell arrays, like internal standards)
        %                     mixing_ratio = getNestedMixingRatio(session.spot, 'mixing_ratio', uniqueBracketIds, unique_PRMs, nSpots);
        %
        %                     % Skip if no PRMs or no brackets (as requested: disregard empties)
        %                     if isempty(unique_PRMs) || isempty(uniqueBracketIds)
        %                         continue
        %                     end
        %
        %                     % Duplicate per PRM and per bracket
        %                     for p = 1:numel(unique_PRMs)
        %                         for b = 1:numel(uniqueBracketIds)
        %
        %                             % Update sensitivities table with prm_sensitivities values
        %                             sens_tbl = sensitivities{b, p};
        %                             common_cols = intersect(sens_tbl.Properties.VariableNames, prm_sensitivities.Properties.VariableNames);
        %                             nonzero_rows = any(table2array(prm_sensitivities) ~= 0, 2);
        %                             for col = common_cols
        %                                 sens_tbl{nonzero_rows, col} = prm_sensitivities{nonzero_rows, col};
        %                             end
        %                             sensitivities{b, p} = sens_tbl;
        %
        %                             for k = 1:nSpots
        %                                 allProjects{end+1,1} = projectName;
        %                                 allSessions{end+1,1} = sessionName;
        %                                 alldates{end+1,1} = sessiondate;
        %                                 allSpotnames{end+1,1} = spotnames{k};
        %                                 allSamples{end+1,1} = sample{k};
        %                                 allMaterials{end+1,1} = material{k};
        %                                 allUnmixingVia{end+1,1} = unmixing_via{k};
        %                                 allMatrixOnlyTracer{end+1,1} = matrix_only_tracer{k};
        %                                 allMatrixFromSpot{end+1,1} = matrix_from_spot{k};
        %
        %                                 if numel(type) >= k && ~ismissing(type(k))
        %                                     allTypes{end+1,1} = type(k);
        %                                 else
        %                                     allTypes{end+1,1} = "";
        %                                 end
        %
        %                                 if numel(referencematerial) >= k && ~ismissing(referencematerial(k))
        %                                     allRefMaterials{end+1,1} = referencematerial(k);
        %                                 else
        %                                     allRefMaterials{end+1,1} = "";
        %                                 end
        %
        %                                 allIntTimePoints{end+1,1} = integer_time_points(k);
        %
        %                                 allIntStdElements{end+1,1}     = getNestedValue(internal_standard_element, b, p, k, "");
        %                                 allIntStdValues{end+1,1}       = getNestedValue(internal_standard_value, b, p, k, NaN);
        %                                 allIntStdOxValues{end+1,1}     = getNestedValue(internal_standard_value_ox, b, p, k, NaN);
        %                                 allFeRatios{end+1,1}           = getNestedValue(Fe_ratio_for_total_oxide, b, p, k, NaN);
        %
        %                                 allIntStdElements_Incl{end+1,1} = getNestedValue(internal_standard_inclusion, b, p, k, "");
        %                                 allIntStdValues_Incl{end+1,1}   = getNestedValue(internal_standard_mass_fraction_inclusion, b, p, k, NaN);
        %
        %                                 allSecondIntStdElements_Incl{end+1,1} = getNestedValue(second_internal_standard_inclusion, b, p, k, "");
        %                                 allSecondIntStdValues_Incl{end+1,1}   = getNestedValue(second_internal_standard_mass_fraction_inclusion, b, p, k, NaN);
        %                                 allIntStdOxValues_Incl{end+1,1}       = getNestedValue(internal_standard_oxide_wt_inclusion, b, p, k, NaN);
        %                                 allFeRatios_Incl{end+1,1}             = getNestedValue(Fe_ratio_for_total_oxide_inclusion, b, p, k, NaN);
        %                                 allMixingRatios{end+1,1} = getNestedMixingRatioValue(mixing_ratio, b, p, k);
        %
        %                                 allQuantifiedWith{end+1,1} = unique_PRMs(p);
        %                                 allusedBracket_id{end+1,1} = uniqueBracketIds(b);
        %                             end
        %
        %                             allbkgdCpsRows_mat{end+1,1} = bkgdCps_mat;
        %                             allbkgdCpsRows_incl{end+1,1} = bkgdCps_incl;
        %                             allbkgdCpsRows_mix{end+1,1} = bkgdCps_mix;
        %
        %                             allLaserParamTables{end+1,1} = laserParamTable;
        %                             allICPMSParamTables{end+1,1} = ICPMSParamTable;
        %
        %                             allSensitivitiesRows{end+1,1} = ensureAnalyteTable(sensitivities{b,p}, analyteNames, nSpots);
        %                             allmatrixcompRows{end+1,1}   = ensureAnalyteTable(matrix_composition{b,p}, analyteNames, nSpots);
        %                             allinclcompRows{end+1,1}     = ensureAnalyteTable(inclusion_composition{b,p}, analyteNames, nSpots);
        %                             allmatrixlodRows{end+1,1}    = ensureAnalyteTable(matrix_limits_of_detection{b,p}, analyteNames, nSpots);
        %                             allincllodRows{end+1,1}      = ensureAnalyteTable(inclusion_limits_of_detection{b,p}, analyteNames, nSpots);
        %                             allmixcompRows{end+1,1}      = ensureAnalyteTable(mix_composition{b,p}, analyteNames, nSpots);
        %
        %                         end
        %                     end
        %                 end
        %             end
        %
        %             %%%%%% Laser Parameters %%%%
        %             for i = 1:numel(allLaserParamTables)
        %                 if isempty(allLaserParamTables{i}) || height(allLaserParamTables{i}) == 0
        %                     try
        %                     nRows = height(allbkgdCpsRows_mat{i});
        %                     catch
        %                         keyboard
        %                     end
        %                     % Find a non-empty table to copy column info and types
        %                     for j = 1:numel(allLaserParamTables)
        %                         if ~isempty(allLaserParamTables{j}) && height(allLaserParamTables{j}) > 0
        %                             refTable = allLaserParamTables{j};
        %                             varNames = refTable.Properties.VariableNames;
        %                             varTypes = varfun(@class, refTable, 'OutputFormat', 'cell');
        %                             emptyData = cell(1, numel(varNames));
        %                             for k = 1:numel(varNames)
        %                                 switch varTypes{k}
        %                                     case {'double', 'single'}
        %                                         emptyData{k} = zeros(nRows,1);
        %                                     case {'cell'}
        %                                         emptyData{k} = cell(nRows,1);
        %                                     case {'char', 'string'}
        %                                         emptyData{k} = repmat({''}, nRows,1);
        %                                     case {'datetime'}
        %                                         emptyData{k} = NaT(nRows,1);
        %                                     case {'logical'}
        %                                         emptyData{k} = false(nRows,1);
        %                                     otherwise
        %                                         emptyData{k} = cell(nRows,1); % fallback
        %                                 end
        %                             end
        %                             allLaserParamTables{i} = table(emptyData{:}, 'VariableNames', varNames);
        %                             break;
        %                         end
        %                     end
        %                 end
        %             end
        %
        %             % Now you can safely concatenate
        %             allLaserParamTables = vertcat(allLaserParamTables{:});
        %
        %             %%%%%% ICP-MS Parameters %%%%
        %
        %             % filepath: c:\Users\Sebastian\OneDrive - Universitaet Bern\PhD\Data\DataFrame\expandAndCombineICPMSParamTables.m
        %             for i = 1:numel(allICPMSParamTables)
        %                 nRows = height(allbkgdCpsRows_mat{i});
        %                 tbl = allICPMSParamTables{i};
        %                 if isempty(tbl) || height(tbl) == 0
        %                     % Find a non-empty reference table
        %                     for j = 1:numel(allICPMSParamTables)
        %                         if ~isempty(allICPMSParamTables{j}) && height(allICPMSParamTables{j}) > 0
        %                             refTable = allICPMSParamTables{j};
        %                             varNames = refTable.Properties.VariableNames;
        %                             emptyData = cell(1, numel(varNames));
        %                             for k = 1:numel(varNames)
        %                                 refCol = refTable.(varNames{k});
        %                                 refType = class(refCol);
        %                                 if strcmp(refType, 'cell')
        %                                     % Check the content type of the first non-empty cell
        %                                     firstNonEmpty = find(~cellfun(@isempty, refCol), 1);
        %                                     if isempty(firstNonEmpty)
        %                                         % Default to empty string cell
        %                                         emptyData{k} = repmat({''}, nRows, 1);
        %                                     else
        %                                         content = refCol{firstNonEmpty};
        %                                         if ischar(content) || isstring(content)
        %                                             emptyData{k} = repmat({''}, nRows, 1);
        %                                         elseif isnumeric(content)
        %                                             emptyData{k} = num2cell(zeros(nRows, 1));
        %                                         else
        %                                             emptyData{k} = cell(nRows, 1);
        %                                         end
        %                                     end
        %                                 elseif strcmp(refType, 'double') || strcmp(refType, 'single')
        %                                     emptyData{k} = zeros(nRows, 1);
        %                                 elseif strcmp(refType, 'char') || strcmp(refType, 'string')
        %                                     emptyData{k} = repmat({''}, nRows, 1);
        %                                 elseif strcmp(refType, 'datetime')
        %                                     emptyData{k} = NaT(nRows, 1);
        %                                 elseif strcmp(refType, 'logical')
        %                                     emptyData{k} = false(nRows, 1);
        %                                 else
        %                                     emptyData{k} = cell(nRows, 1); % fallback
        %                                 end
        %                             end
        %                             tbl = table(emptyData{:}, 'VariableNames', varNames);
        %                             break;
        %                         end
        %                     end
        %                 elseif height(tbl) == 1 && nRows > 1
        %                     tbl = tbl(ones(nRows,1), :);
        %                 end
        %                 allICPMSParamTables{i} = tbl;
        %             end
        %
        %             allICPMSParamTables = vertcat(allICPMSParamTables{:});
        %
        %             % Clean dates if numerics slipped in
        %             if ~isempty(alldates)
        %                 try
        %                     numMask = cellfun(@isnumeric, alldates);
        %                     alldates(numMask) = {''};
        %                 catch
        %                     % ignore
        %                 end
        %             end
        %
        %             % Create metadata table
        %             Metadata_Table = table(allProjects, allSessions, alldates, allSpotnames, ...
        %                 allSamples, allMaterials, allTypes, allRefMaterials, ...
        %                 allIntTimePoints, ...
        %                 allIntStdElements, allIntStdValues, allIntStdOxValues, allFeRatios,  ...
        %                 allIntStdElements_Incl, allIntStdValues_Incl, ...
        %                 allSecondIntStdElements_Incl, allSecondIntStdValues_Incl, ...
        %                 allIntStdOxValues_Incl, allFeRatios_Incl, ...
        %                 allUnmixingVia, allMatrixOnlyTracer, allMatrixFromSpot, ...
        %                 allMixingRatios, ...
        %                 allQuantifiedWith, allusedBracket_id, ...
        %                 'VariableNames', {'Project', 'Session', 'Date', 'Spotname', ...
        %                 'Sample', 'Material', 'Type', 'Reference_Material', ...
        %                 'Integer_Time_Points', ...
        %                 'Internal_Standard_Element', 'Internal_Standard_Value', ...
        %                 'Internal_Standard_Oxide_Value', 'Fe_Ratio_For_Total_Oxide', ...
        %                 'Internal_Standard_Element_Inclusion', 'Internal_Standard_Value_Inclusion', ...
        %                 'Second_Internal_Standard_Element_Inclusion', 'Second_Internal_Standard_Value_Inclusion', ...
        %                 'Internal_Standard_Oxide_Value_Inclusion', 'Fe_Ratio_For_Total_Oxide_Inclusion', ...
        %                 'Unmixing_Via', 'Matrix_Only_Tracer', 'Matrix_From_Spot', ...
        %                 'Mixing_Ratios', ...
        %                 'Quantified_with', 'used_bracket_id'});
        %
        %             % Date parsing
        %             if ~isempty(Metadata_Table)
        %                 try
        %                     Metadata_Table.Date_Str = datetime(Metadata_Table.Date, 'InputFormat', 'yyyyMMdd');
        %                 catch
        %                     Metadata_Table.Date_Str = datetime(Metadata_Table.Date);
        %                 end
        %                 Metadata_Table.Date_Num = datenum(Metadata_Table.Date_Str);
        %             end
        %
        %             % Build each numeric block safely
        %             allmatrixcompTable = buildNumericBlock(allmatrixcompRows, 'Matrix_Composition');
        %             allmatrixlodTable = buildNumericBlock(allmatrixlodRows, 'Matrix_LOD');
        %             allinclusionlodTable = buildNumericBlock(allincllodRows, 'Inclusion_LOD');
        %             allinclusioncompTable = buildNumericBlock(allinclcompRows, 'Inclusion_Composition');
        %             allmixcompTable = buildNumericBlock(allmixcompRows, 'Inclusion_Composition');
        %
        %             allbkgdCpsTable_mat = buildNumericBlock(allbkgdCpsRows_mat, 'Bkgd_Corrected_CPS_mat');
        %             allbkgdCpsTable_incl = buildNumericBlock(allbkgdCpsRows_incl, 'Bkgd_Corrected_CPS_incl');
        %             allbkgdCpsTable_mix = buildNumericBlock(allbkgdCpsRows_mix, 'Bkgd_Corrected_CPS_mix');
        %
        %             allsensTable = buildNumericBlock(allSensitivitiesRows, 'Sensitivities');
        %
        %             % --- Rename analyte columns with suffixes ---
        %             allmatrixcompTable = renameAnalyteColumns(allmatrixcompTable, '_mat_ugg');
        %             allmatrixlodTable  = renameAnalyteColumns(allmatrixlodTable, '_mat_lod');
        %             allinclusioncompTable = renameAnalyteColumns(allinclusioncompTable, '_incl_ugg');
        %             allinclusionlodTable  = renameAnalyteColumns(allinclusionlodTable, '_incl_lod');
        %             allmixcompTable = renameAnalyteColumns(allmixcompTable, '_mix_ugg');
        %             allbkgdCpsTable_mat = renameAnalyteColumns(allbkgdCpsTable_mat, '_mat_cps');
        %             allbkgdCpsTable_incl = renameAnalyteColumns(allbkgdCpsTable_incl, '_incl_cps');
        %             allbkgdCpsTable_mix = renameAnalyteColumns(allbkgdCpsTable_mix, '_mix_cps');
        %             allsensTable = renameAnalyteColumns(allsensTable, '_sens');
        %
        %             % Remove Data_Source column from numeric tables (if present)
        %             if ismember('Data_Source', allmatrixcompTable.Properties.VariableNames)
        %                 allmatrixcompTable.Data_Source = [];
        %             end
        %             if ismember('Data_Source', allmatrixlodTable.Properties.VariableNames)
        %                 allmatrixlodTable.Data_Source = [];
        %             end
        %             if ismember('Data_Source', allinclusioncompTable.Properties.VariableNames)
        %                 allinclusioncompTable.Data_Source = [];
        %             end
        %             if ismember('Data_Source', allinclusionlodTable.Properties.VariableNames)
        %                 allinclusionlodTable.Data_Source = [];
        %             end
        %             if ismember('Data_Source', allmixcompTable.Properties.VariableNames)
        %                 allmixcompTable.Data_Source = [];
        %             end
        %             if ismember('Data_Source', allbkgdCpsTable_mat.Properties.VariableNames)
        %                 allbkgdCpsTable_mat.Data_Source = [];
        %             end
        %             if ismember('Data_Source', allbkgdCpsTable_incl.Properties.VariableNames)
        %                 allbkgdCpsTable_incl.Data_Source = [];
        %             end
        %             if ismember('Data_Source', allbkgdCpsTable_mix.Properties.VariableNames)
        %                 allbkgdCpsTable_mix.Data_Source = [];
        %             end
        %             if ismember('Data_Source', allsensTable.Properties.VariableNames)
        %                 allsensTable.Data_Source = [];
        %             end
        %
        %             % Horizontally concatenate with metadata (assuming row order matches)
        %             Base_Table = [Metadata_Table, ...
        %                 allmatrixcompTable, ...
        %                 allmatrixlodTable, ...
        %                 allinclusioncompTable, ...
        %                 allinclusionlodTable, ...
        %                 allmixcompTable,...
        %                 allbkgdCpsTable_mat,...
        %                 allbkgdCpsTable_incl,...
        %                 allbkgdCpsTable_mix,...
        %                 allsensTable,...
        %                 allLaserParamTables,...
        %                 allICPMSParamTables];
        %
        %             % Normalize some textual columns to cellstr for downstream compatibility
        %             if ~isempty(Base_Table)
        %                 Base_Table.Type = normalizeToCellstr(Base_Table.Type);
        %                 Base_Table.Material = normalizeToCellstr(Base_Table.Material);
        %                 Base_Table.Reference_Material = normalizeToCellstr(Base_Table.Reference_Material);
        %                 Base_Table.Quantified_with = normalizeToCellstr(Base_Table.Quantified_with);
        %
        %                 % Replace 0 with NaN in numeric columns only
        %                 numericCols = varfun(@isnumeric, Base_Table, 'OutputFormat', 'uniform');
        %                 if any(numericCols)
        %                     X = Base_Table{:, numericCols};
        %                     X(X == 0) = NaN;
        %                     Base_Table{:, numericCols} = X;
        %                 end
        %
        %             end
        %
        %
        %             % ----------------- Helpers -----------------
        %             function val = getNestedValue(C, b, p, k, defaultVal)
        %                 % Safely extracts C{b,p}{k} allowing various shapes and types
        %                 val = defaultVal;
        %                 try
        %                     if iscell(C) && numel(C) >= (p-1)*size(C,1)+b && ~isempty(C)
        %                         X = C{b,p};
        %                         if iscell(X)
        %                             if numel(X) >= k && ~isempty(X{k})
        %                                 val = X{k};
        %                             end
        %                         elseif isstring(X) || ischar(X)
        %                             if numel(X) >= k
        %                                 val = X(k);
        %                             end
        %                         elseif isnumeric(X)
        %                             if numel(X) >= k
        %                                 val = X(k);
        %                             end
        %                         end
        %                     end
        %                 catch
        %                     val = defaultVal;
        %                 end
        %             end
        %
        %             function T = ensureAnalyteTable(T, analyteNames_, nRows_)
        %                 % Ensure a table with nRows_ rows and analyte columns exists
        %                 if ~istable(T) || isempty(T)
        %                     T = table('Size', [nRows_, numel(analyteNames_)], ...
        %                         'VariableTypes', repmat("double", 1, numel(analyteNames_)), ...
        %                         'VariableNames', cellstr(analyteNames_));
        %                     return
        %                 end
        %                 % Coerce non-double variables to double
        %                 for v = 1:width(T)
        %                     if ~isnumeric(T{:,v})
        %                         try
        %                             T{:,v} = double(T{:,v});
        %                         catch
        %                             T{:,v} = zeros(height(T),1);
        %                         end
        %                     end
        %                 end
        %                 % Fix height
        %                 if height(T) ~= nRows_
        %                     headers = T.Properties.VariableNames;
        %                     TT = table('Size', [nRows_, numel(headers)], ...
        %                         'VariableTypes', repmat("double", 1, numel(headers)), ...
        %                         'VariableNames', headers);
        %                     nCopy = min(height(T), nRows_);
        %                     if nCopy > 0
        %                         TT(1:nCopy, :) = T(1:nCopy, :);
        %                     end
        %                     T = TT;
        %                 end
        %             end
        %
        %             function sortedHeaders = getSortedHeaders(rowsCell)
        %                 if isempty(rowsCell)
        %                     sortedHeaders = {};
        %                     return
        %                 end
        %                 isT = cellfun(@istable, rowsCell);
        %                 if ~any(isT)
        %                     sortedHeaders = {};
        %                     return
        %                 end
        %                 headersCell = cellfun(@(t) t.Properties.VariableNames, rowsCell(isT), 'UniformOutput', false);
        %                 if isempty(headersCell)
        %                     sortedHeaders = {};
        %                     return
        %                 end
        %                 allHeaders = unique([headersCell{:}], 'stable');
        %                 nums = cellfun(@localExtractNum, allHeaders);
        %                 [~, sortIdx] = sort(nums); % NaNs go last
        %                 sortedHeaders = allHeaders(sortIdx);
        %             end
        %
        %             function n = localExtractNum(h)
        %                 tok = regexp(h, '\d+', 'match', 'once');
        %                 if isempty(tok)
        %                     n = NaN;
        %                 else
        %                     n = str2double(tok);
        %                 end
        %             end
        %
        %             function tableWithAllColumns = addMissingColumns(inputTable, targetHeaders)
        %                 if isempty(targetHeaders)
        %                     tableWithAllColumns = inputTable;
        %                     return
        %                 end
        %                 missingCols = setdiff(targetHeaders, inputTable.Properties.VariableNames, 'stable');
        %                 if isempty(missingCols)
        %                     tableWithAllColumns = inputTable(:, targetHeaders);
        %                     return
        %                 end
        %                 missingData = zeros(height(inputTable), numel(missingCols));
        %                 missingTable = array2table(missingData, 'VariableNames', missingCols);
        %                 tableWithAllColumns = [inputTable missingTable];
        %                 tableWithAllColumns = tableWithAllColumns(:, targetHeaders);
        %             end
        %
        %             function outTable = buildNumericBlock(rowsCell, label)
        %                 if isempty(rowsCell)
        %                     outTable = table();
        %                     return
        %                 end
        %                 headers = getSortedHeaders(rowsCell);
        %                 if ~isempty(rowsCell)
        %                     rowsCell = cellfun(@(t) addMissingColumns(t, headers), rowsCell, 'UniformOutput', false);
        %                 end
        %                 if isempty(rowsCell)
        %                     outTable = table();
        %                     return
        %                 end
        %                 outTable = vertcat(rowsCell{:});
        %                 if isempty(outTable)
        %                     return
        %                 end
        %                 outTable = addvars(outTable, repmat({label}, height(outTable), 1), ...
        %                     'Before', 1, 'NewVariableNames', 'Data_Source');
        %             end
        %
        %             function T = renameAnalyteColumns(T, suffix)
        %                 if isempty(T)
        %                     return
        %                 end
        %                 metaCols = {'Data_Source'};
        %                 for v = 1:width(T)
        %                     if ~ismember(T.Properties.VariableNames{v}, metaCols)
        %                         T.Properties.VariableNames{v} = [T.Properties.VariableNames{v} suffix];
        %                     end
        %                 end
        %             end
        %
        %             function T = normalizeToCellstr(T)
        %                 if istable(T) || isstruct(T)
        %                     return
        %                 end
        %                 if iscell(T)
        %                     try
        %                         T = cellfun(@char, T, 'UniformOutput', false);
        %                     catch
        %                         T = cellfun(@(x) char(string(x)), T, 'UniformOutput', false);
        %                     end
        %                 else
        %                     T = cellstr(string(T));
        %                 end
        %             end
        %
        %             function C = getNestedField(spotStruct, fieldName, uniqueBracketIds, unique_PRMs, nSpots, defaultVal)
        %                 if isfield(spotStruct, fieldName)
        %                     C = spotStruct.(fieldName);
        %                 else
        %                     C = cell(numel(uniqueBracketIds), numel(unique_PRMs));
        %                     for b = 1:numel(uniqueBracketIds)
        %                         for p = 1:numel(unique_PRMs)
        %                             if ischar(defaultVal) || isstring(defaultVal)
        %                                 C{b, p} = repmat({defaultVal}, nSpots, 1);
        %                             else
        %                                 C{b, p} = repmat(defaultVal, nSpots, 1);
        %                             end
        %                         end
        %                     end
        %                 end
        %             end
        %
        %             function C = getNestedTable(spotStruct, fieldName, uniqueBracketIds, unique_PRMs, nSpots, analyteNames)
        %                 if isfield(spotStruct, fieldName)
        %                     C = spotStruct.(fieldName);
        %                 else
        %                     C = cell(numel(uniqueBracketIds), numel(unique_PRMs));
        %                     for b = 1:numel(uniqueBracketIds)
        %                         for p = 1:numel(unique_PRMs)
        %                             C{b, p} = table('Size', [nSpots, numel(analyteNames)], ...
        %                                 'VariableTypes', repmat("double", 1, numel(analyteNames)), ...
        %                                 'VariableNames', cellstr(analyteNames));
        %                         end
        %                     end
        %                 end
        %             end
        %
        %             function C = getNestedMixingRatio(spotStruct, fieldName, uniqueBracketIds, unique_PRMs, nSpots)
        %                 if isfield(spotStruct, fieldName)
        %                     C = spotStruct.(fieldName);
        %                 else
        %                     % Assume 3 mixing ratios per bracket/PRM, fill with NaN
        %                     C = cell(numel(uniqueBracketIds), numel(unique_PRMs));
        %                     for b = 1:numel(uniqueBracketIds)
        %                         for p = 1:numel(unique_PRMs)
        %                             C{b, p} = repmat({NaN}, nSpots, 1);
        %                         end
        %                     end
        %                 end
        %             end
        %
        %             function val = getNestedMixingRatioValue(C, b, p, k)
        %                 % Returns a cell array of mixing ratios for spot k, bracket b, PRM p
        %                 val = [];
        %                 try
        %                     if iscell(C) && numel(C) >= (p-1)*size(C,1)+b && ~isempty(C)
        %                         X = C{b,p};
        %                         if iscell(X)
        %                             if numel(X) >= k && ~isempty(X{k})
        %                                 val = X{k};
        %                             else
        %                                 val = NaN;
        %                             end
        %                         elseif isnumeric(X)
        %                             if numel(X) >= k
        %                                 val = X(k);
        %                             else
        %                                 val = NaN;
        %                             end
        %                         end
        %                     end
        %                 catch
        %                     val = NaN;
        %                 end
        %             end
        %
        %             function T = struct_to_table(S)
        %                 % Converts a struct S with fields of arrays/cells/chars to a table.
        %                 % Singleton fields are duplicated to match the length of the longest field.
        %                 if isempty(S)
        %                     T = table();
        %                     return
        %                 end
        %                 fields = fieldnames(S);
        %                 lens = zeros(numel(fields),1);
        %                 for i = 1:numel(fields)
        %                     col = S.(fields{i});
        %                     if ischar(col) || (isstring(col) && isscalar(col))
        %                         lens(i) = 1;
        %                     else
        %                         lens(i) = numel(col);
        %                     end
        %                 end
        %                 n = max(lens);
        %                 T = table();
        %                 for i = 1:numel(fields)
        %                     col = S.(fields{i});
        %                     % Handle char arrays and string scalars
        %                     if ischar(col) || (isstring(col) && isscalar(col))
        %                         col = repmat({col}, n, 1);
        %                     elseif iscell(col) && numel(col) == 1 && n > 1
        %                         col = repmat(col, n, 1);
        %                     elseif isnumeric(col) && numel(col) == 1 && n > 1
        %                         col = repmat(col, n, 1);
        %                     elseif numel(col) ~= n
        %                         error('Field %s has %d elements, expected %d.', fields{i}, numel(col), n);
        %                     end
        %                     % Ensure column is column vector
        %                     if isrow(col) && ~ischar(col)
        %                         col = col';
        %                     end
        %                     T.(fields{i}) = col;
        %                 end
        %             end
        %
        %
        %         end


        function Base_Table = build_table_from_base(app)
            % --- Get checked nodes from SpotMetaTree ---
            checkedMetaNodes = app.SpotMetaTree.CheckedNodes;
            if isempty(checkedMetaNodes)
                Base_Table = table();
                return
            end
            checkedMetaLabels = string({checkedMetaNodes.NodeData}); % assumes NodeData is string or char

            % Helper to check if a label is checked
            isChecked = @(label) any(checkedMetaLabels == label);

            % --- Always needed: Metadata ---
            allProjects = {};
            allSessions = {};
            allComments = {};
            allAnalysts = {};
            allProcessors = {};
            allSpotnames = {};
            alldates = {};
            allSamples = {};
            allMaterials = {};
            allLocations = {};


            allTypes = {};
            allRefMaterials = {};
            allIntTimePoints = {};
            allQuantifiedWith = {};
            allusedBracket_id = {};

            % Internal standardization
            allIntStdElements = {};
            allIntStdValues = {};
            allIntStdOxValues = {};
            allFeRatios = {};
            allIntStdElements_Incl = {};
            allIntStdValues_Incl = {};
            allSecondIntStdElements_Incl = {};
            allSecondIntStdValues_Incl = {};
            allIntStdOxValues_Incl = {};
            allFeRatios_Incl = {};

            % Host-Inclusion-unmixing
            allUnmixingVia = {};
            allMatrixOnlyTracer = {};
            allMatrixFromSpot = {};
            allMixingRatios = {};

            % Sensitivities
            allSensitivitiesRows = {};
            % Matrix/Host mass fractions
            allmatrixcompRows = {};
            % Inclusion mass fractions
            allinclcompRows = {};
            % Matrix/Host limits of detection
            allmatrixlodRows = {};
            % Inclusion limits of detection
            allincllodRows = {};
            % Mix mass fractions
            allmixcompRows = {};

            % cps
            allbkgdCpsRows_mat = {};
            allbkgdCpsRows_incl = {};
            allbkgdCpsRows_mix = {};

            % LA parameters
            allLaserParamTables = {};
            % ICP-MS parameters
            allICPMSParamTables = {};

            % Get checked nodes from the project/session tree
            checkedNodes = app.ProjectSessionTree.CheckedNodes;
            checkedIndices = [];
            for n = 1:numel(checkedNodes)
                nodeData = checkedNodes(n).NodeData;
                if ~isempty(nodeData) && numel(nodeData) == 2 && nodeData(2) > 0
                    checkedIndices = [checkedIndices; nodeData];
                end
            end

            for i = 1:numel(app.base)
                projectName = app.base(i).project_name;
                for j = 1:numel(app.base(i).project)
                    if isempty(checkedIndices) || ~ismember([i, j], checkedIndices, 'rows')
                        continue
                    end
                    if isfield(app.base(i).project(j), 'session')
                        session = app.base(i).project(j).session;
                    else
                        continue
                    end

                    % Session meta
                    if isfield(session, 'session_name') && ~isempty(session.session_name)
                        sessionName = char(session.session_name);
                    else
                        sessionName = '';
                    end
                    if isfield(session, 'meta') && isfield(session.meta, 'date') && ~isempty(session.meta.date)
                        sessiondate = session.meta.date;
                    else
                        sessiondate = '';
                    end
                    if isfield(session.meta, 'session_comment') && ~isempty(session.meta.session_comment)
                        sessionComment = char(session.meta.session_comment);
                    else
                        sessionComment = '';
                    end
                    if isfield(session.meta, 'analyst') && ~isempty(session.meta.analyst)
                        analyst = char(session.meta.analyst);
                    else
                        analyst = '';
                    end
                    if isfield(session.meta, 'processed_by') && ~isempty(session.meta.processed_by)
                        processed_by = char(session.meta.processed_by);
                    else
                        processed_by = '';
                    end

                    % Spot block
                    if ~isfield(session, 'spot') || isempty(session.spot)
                        continue
                    end
                    spotnames = session.spot.name;
                    nSpots = numel(spotnames);

                    % Sample, material
                    if isfield(session.meta, 'sample') && ~isempty(session.meta.sample)
                        sample = session.meta.sample(:);
                    else
                        sample = repmat({''}, nSpots, 1);
                    end
                    if isfield(session.meta, 'material') && ~isempty(session.meta.material)
                        material = session.meta.material(:);
                    else
                        material = repmat({''}, nSpots, 1);
                    end
                    if isfield(session.meta, 'location') && ~isempty(session.meta.location)
                        location = session.meta.location(:);
                    else
                        location = repmat({''}, nSpots, 1);
                    end

                    % Type and PRMs
                    if isfield(session.spot, 'type') && ~isempty(session.spot.type)
                        type = string(session.spot.type(:));
                    else
                        type = strings(nSpots, 1);
                    end
                    if isfield(session.spot, 'referencematerial') && ~isempty(session.spot.referencematerial)
                        referencematerial = string(session.spot.referencematerial(:));
                    else
                        referencematerial = strings(nSpots, 1);
                    end

                    % Determine PRMs from type == "PRM"
                    prm_indices = (type == "PRM");
                    if any(prm_indices)
                        prm_materials = referencematerial(prm_indices);
                        unique_PRMs = unique(prm_materials);
                    else
                        unique_PRMs = string.empty(0,1);
                    end

                    % Bracket IDs
                    if isfield(session.spot, 'bracket_id') && ~isempty(session.spot.bracket_id)
                        bracket_id = session.spot.bracket_id(:);
                        if ~isnumeric(bracket_id)
                            bracket_id = zeros(nSpots,1);
                        end
                        uniqueBracketIds = unique(bracket_id);
                        uniqueBracketIds = uniqueBracketIds(uniqueBracketIds ~= 0);
                    else
                        bracket_id = zeros(nSpots, 1); % 0 marks missing
                        uniqueBracketIds = [];
                    end

                    % Integer time points
                    if isfield(session.spot, 'integer_time_points') && ~isempty(session.spot.integer_time_points)
                        integer_time_points = session.spot.integer_time_points(:);
                        if ~isnumeric(integer_time_points)
                            integer_time_points = NaN(nSpots,1);
                        end
                    else
                        integer_time_points = NaN(nSpots, 1);
                    end

                    % Analyte names
                    if isfield(session.spot, 'analytes') && ~isempty(session.spot.analytes)
                        analyteNames = strtrim(string(session.spot.analytes(:)));
                    else
                        analyteNames = strings(0,1);
                    end

                    % --- Only process blocks if checked in SpotMetaTree ---
                    % LA parameters
                    laserParamTable = table();
                    if isChecked("LA parameters")
                        if isfield(session, 'meta') && isfield(session.meta, 'laser_parameters')
                            laserParamStruct = session.meta.laser_parameters;
                            
                            laserParamTable = struct_to_table(laserParamStruct);
 
                        else
                            laserParamTable = table();
                        end
                    end

                    % ICP-MS parameters
                    ICPMSParamTable = table();
                    if isChecked("ICP-MS parameters")
                        if isfield(session, 'meta') && isfield(session.meta, 'icp_ms_parameters')
                            ICPMSParamStruct = session.meta.icp_ms_parameters;
                            ICPMSParamTable = struct_to_table(ICPMSParamStruct);
                        else
                            ICPMSParamTable = table();
                        end
                    end

                    % Internal standardization
                    if isChecked("Internal standardization")
                        internal_standard_element = getNestedField(session.spot, 'internal_standard', uniqueBracketIds, unique_PRMs, nSpots, "");
                        internal_standard_value = getNestedField(session.spot, 'internal_standard_mass_fraction', uniqueBracketIds, unique_PRMs, nSpots, NaN);
                        internal_standard_value_ox = getNestedField(session.spot, 'internal_standard_oxide_wt', uniqueBracketIds, unique_PRMs, nSpots, NaN);
                        Fe_ratio_for_total_oxide = getNestedField(session.spot, 'Fe_ratio_for_total_oxide', uniqueBracketIds, unique_PRMs, nSpots, NaN);
                        internal_standard_inclusion = getNestedField(session.spot, 'internal_standard_inclusion', uniqueBracketIds, unique_PRMs, nSpots, "");
                        internal_standard_mass_fraction_inclusion = getNestedField(session.spot, 'internal_standard_mass_fraction_inclusion', uniqueBracketIds, unique_PRMs, nSpots, NaN);
                        second_internal_standard_inclusion = getNestedField(session.spot, 'second_internal_standard_inclusion', uniqueBracketIds, unique_PRMs, nSpots, "");
                        second_internal_standard_mass_fraction_inclusion = getNestedField(session.spot, 'second_internal_standard_mass_fraction_inclusion', uniqueBracketIds, unique_PRMs, nSpots, NaN);
                        internal_standard_oxide_wt_inclusion = getNestedField(session.spot, 'internal_standard_oxide_wt_inclusion', uniqueBracketIds, unique_PRMs, nSpots, NaN);
                        Fe_ratio_for_total_oxide_inclusion = getNestedField(session.spot, 'Fe_ratio_for_total_oxide_inclusion', uniqueBracketIds, unique_PRMs, nSpots, NaN);
                    end

                    % Sensitivities
                    if isChecked("Sensitivities")
                        if isfield(app.base(i).project(j).session.spot, 'prm_sensitivities')
                            prm_sensitivities = app.base(i).project(j).session.spot.prm_sensitivities;
                        end
                        sensitivities = getNestedTable(session.spot, 'sensitivities', uniqueBracketIds, unique_PRMs, nSpots, analyteNames);
                    end

                    % Matrix/Host mass fractions
                    if isChecked("Matrix/Host mass fractions")
                        matrix_composition = getNestedTable(session.spot, 'matrix_composition', uniqueBracketIds, unique_PRMs, nSpots, analyteNames);
                    end

                    % Matrix/Host limits of detection
                    if isChecked("Matrix/Host limits of detection") || isChecked("Matrix/Host mass fractions")
                        matrix_limits_of_detection = getNestedTable(session.spot, 'matrix_limits_of_detection', uniqueBracketIds, unique_PRMs, nSpots, analyteNames);
                    end

                    % Inclusion blocks
                    if isChecked("Inclusion mass fractions")
                        inclusion_composition = getNestedTable(session.spot, 'inclusion_composition', uniqueBracketIds, unique_PRMs, nSpots, analyteNames);
                    end
                    if isChecked("Inclusion limits of detection") || isChecked("Inclusion mass fractions") || isChecked("Mix mass fractions")
                        inclusion_limits_of_detection = getNestedTable(session.spot, 'inclusion_limits_of_detection', uniqueBracketIds, unique_PRMs, nSpots, analyteNames);
                    end
                    if isChecked("Mix mass fractions")
                        mix_composition = getNestedTable(session.spot, 'mix_composition', uniqueBracketIds, unique_PRMs, nSpots, analyteNames);
                    end

                    % Mixing ratios
                    if isChecked("Host-Inclusion-unmixing")
                        mixing_ratio = getNestedMixingRatio(session.spot, 'mixing_ratio', uniqueBracketIds, unique_PRMs, nSpots);
                        % Host-Inclusion-unmixing
                        if isfield(session.spot, 'unmixing_via') && ~isempty(session.spot.unmixing_via)
                            unmixing_via = string(session.spot.unmixing_via(:));
                        else
                            unmixing_via = repmat({''}, nSpots, 1);
                        end
                        if isfield(session.spot, 'matrix_only_tracer') && ~isempty(session.spot.matrix_only_tracer)
                            matrix_only_tracer = string(session.spot.matrix_only_tracer(:));
                        else
                            matrix_only_tracer = repmat({''}, nSpots, 1);
                        end
                        if isfield(session.spot, 'matrix_from_spot') && ~isempty(session.spot.matrix_from_spot)
                            matrix_from_spot = string(session.spot.matrix_from_spot(:));
                        else
                            matrix_from_spot = repmat({''}, nSpots, 1);
                        end
                    end

                    % cps
                    %if isChecked("Matrix/Host counts-per-sweep") % this is
                    %commented so the laser_parameters and icp_ms_parameters knows how to fill the empty
                    %tables
                    if isfield(app.base(i).project(j).session.spot, 'bkgd_corrected_cps')
                        bkgdCps_mat = app.base(i).project(j).session.spot.bkgd_corrected_cps;
                    else
                        bkgdCps_mat = table();
                    end
                    %end
                    if isChecked("Inclusion counts-per-sweep")
                        if isfield(app.base(i).project(j).session.spot, 'bkgd_corrected_cps_incl')
                            bkgdCps_incl = app.base(i).project(j).session.spot.bkgd_corrected_cps_incl;
                        else
                            bkgdCps_incl = array2table(zeros(numel(spotnames), numel(app.base(i).project(j).session.spot.analytes)), ...
                                'VariableNames', app.base(i).project(j).session.spot.analytes);
                        end
                    end
                    if isChecked("Mix counts-per-sweep")
                        if isfield(app.base(i).project(j).session.spot, 'bkgd_corrected_cps_mix')
                            bkgdCps_mix = app.base(i).project(j).session.spot.bkgd_corrected_cps_mix;
                        else
                            bkgdCps_mix = array2table(zeros(numel(spotnames), numel(app.base(i).project(j).session.spot.analytes)), ...
                                'VariableNames', app.base(i).project(j).session.spot.analytes);
                        end
                    end

                    % Skip if no PRMs or no brackets (as requested: disregard empties)
                    if isempty(unique_PRMs) || isempty(uniqueBracketIds)
                        continue
                    end

                    % Duplicate per PRM and per bracket
                    for p = 1:numel(unique_PRMs)
                        for b = 1:numel(uniqueBracketIds)
                            for k = 1:nSpots
                                allProjects{end+1,1} = projectName;
                                allSessions{end+1,1} = sessionName;
                                allComments{end+1,1} = sessionComment;
                                allAnalysts{end+1,1} = analyst;
                                allProcessors{end+1,1} = processed_by;

                                alldates{end+1,1} = sessiondate;
                                allSpotnames{end+1,1} = spotnames{k};
                                allSamples{end+1,1} = sample{k};
                                allMaterials{end+1,1} = material{k};
                                allLocations{end+1,1} = location{k};

                                if numel(type) >= k && ~ismissing(type(k))
                                    allTypes{end+1,1} = type(k);
                                else
                                    allTypes{end+1,1} = "";
                                end
                                if numel(referencematerial) >= k && ~ismissing(referencematerial(k))
                                    allRefMaterials{end+1,1} = referencematerial(k);
                                else
                                    allRefMaterials{end+1,1} = "";
                                end
                                allIntTimePoints{end+1,1} = integer_time_points(k);
                                allQuantifiedWith{end+1,1} = unique_PRMs(p);
                                allusedBracket_id{end+1,1} = uniqueBracketIds(b);

                                % Internal standardization
                                if isChecked("Internal standardization")
                                    allIntStdElements{end+1,1}     = getNestedValue(internal_standard_element, b, p, k, "");
                                    allIntStdValues{end+1,1}       = getNestedValue(internal_standard_value, b, p, k, NaN);
                                    allIntStdOxValues{end+1,1}     = getNestedValue(internal_standard_value_ox, b, p, k, NaN);
                                    allFeRatios{end+1,1}           = getNestedValue(Fe_ratio_for_total_oxide, b, p, k, NaN);
                                    allIntStdElements_Incl{end+1,1} = getNestedValue(internal_standard_inclusion, b, p, k, "");
                                    allIntStdValues_Incl{end+1,1}   = getNestedValue(internal_standard_mass_fraction_inclusion, b, p, k, NaN);
                                    allSecondIntStdElements_Incl{end+1,1} = getNestedValue(second_internal_standard_inclusion, b, p, k, "");
                                    allSecondIntStdValues_Incl{end+1,1}   = getNestedValue(second_internal_standard_mass_fraction_inclusion, b, p, k, NaN);
                                    allIntStdOxValues_Incl{end+1,1}       = getNestedValue(internal_standard_oxide_wt_inclusion, b, p, k, NaN);
                                    allFeRatios_Incl{end+1,1}             = getNestedValue(Fe_ratio_for_total_oxide_inclusion, b, p, k, NaN);
                                end
                                if isChecked("Host-Inclusion-unmixing")
                                    allMixingRatios{end+1,1} = getNestedMixingRatioValue(mixing_ratio, b, p, k);
                                    allUnmixingVia{end+1,1} = unmixing_via{k};
                                    allMatrixOnlyTracer{end+1,1} = matrix_only_tracer{k};
                                    allMatrixFromSpot{end+1,1} = matrix_from_spot{k};
                                end
                            end

                            % Sensitivities
                            if isChecked("Sensitivities")

                                % Update sensitivities table with prm_sensitivities values
                                sens_tbl = sensitivities{b, p};
                                common_cols = intersect(sens_tbl.Properties.VariableNames, prm_sensitivities.Properties.VariableNames);
                                nonzero_rows = any(table2array(prm_sensitivities) ~= 0, 2);
                                for col = common_cols
                                    sens_tbl{nonzero_rows, col} = prm_sensitivities{nonzero_rows, col};
                                end
                                sensitivities{b, p} = sens_tbl;


                                allSensitivitiesRows{end+1,1} = ensureAnalyteTable(sensitivities{b,p}, analyteNames, nSpots);
                            end
                            % Matrix/Host mass fractions
                            if isChecked("Matrix/Host mass fractions")
                                allmatrixcompRows{end+1,1}   = ensureAnalyteTable(matrix_composition{b,p}, analyteNames, nSpots);
                            end
                            % Matrix/Host limits of detection
                            if isChecked("Matrix/Host limits of detection") || isChecked("Matrix/Host mass fractions")
                                allmatrixlodRows{end+1,1}    = ensureAnalyteTable(matrix_limits_of_detection{b,p}, analyteNames, nSpots);
                            end
                            % Inclusion mass fractions
                            if isChecked("Inclusion mass fractions")
                                allinclcompRows{end+1,1}     = ensureAnalyteTable(inclusion_composition{b,p}, analyteNames, nSpots);
                            end
                            % Inclusion limits of detection
                            if isChecked("Inclusion limits of detection") || isChecked("Inclusion mass fractions") || isChecked("Mix mass fractions")
                                allincllodRows{end+1,1}      = ensureAnalyteTable(inclusion_limits_of_detection{b,p}, analyteNames, nSpots);
                            end
                            % Mix mass fractions
                            if isChecked("Mix mass fractions")
                                allmixcompRows{end+1,1}      = ensureAnalyteTable(mix_composition{b,p}, analyteNames, nSpots);
                            end

                            % cps
                            %if isChecked("Matrix/Host counts-per-sweep")
                            allbkgdCpsRows_mat{end+1,1} = bkgdCps_mat;
                            %end
                            if isChecked("Inclusion counts-per-sweep")
                                allbkgdCpsRows_incl{end+1,1} = bkgdCps_incl;
                            end
                            if isChecked("Mix counts-per-sweep")
                                allbkgdCpsRows_mix{end+1,1} = bkgdCps_mix;
                            end

                            % LA/ICPMS param tables
                            if isChecked("LA parameters")
                                allLaserParamTables{end+1,1} = laserParamTable;
                            end
                            if isChecked("ICP-MS parameters")
                                allICPMSParamTables{end+1,1} = ICPMSParamTable;
                            end
                        end
                    end
                end
            end


            % --- Build Metadata Table (always included) ---
            Metadata_Table = table(allProjects, allSessions, allComments, allAnalysts, allProcessors, alldates, allSpotnames, ...
                allSamples, allMaterials, allLocations, allTypes, allRefMaterials, ...
                allIntTimePoints, ...
                allQuantifiedWith, allusedBracket_id, ...
                'VariableNames', {'Project', 'Session', 'Comment','Analyst', 'Processed by', 'Date', 'Spotname', ...
                'Sample', 'Material', 'Location', 'Type', 'Reference_Material', ...
                'Integer_Time_Points', ...
                'Quantified_with', 'used_bracket_id'});

            % --- Build only selected numeric/parameter blocks ---
            tableBlocks = {Metadata_Table};

            if isChecked("Internal standardization")
                % Add internal standardization columns to Metadata_Table
                Metadata_Table.Internal_Standard_Element = allIntStdElements;
                Metadata_Table.Internal_Standard_Value = allIntStdValues;
                Metadata_Table.Internal_Standard_Oxide_Value = allIntStdOxValues;
                Metadata_Table.Fe_Ratio_For_Total_Oxide = allFeRatios;
                Metadata_Table.Internal_Standard_Element_Inclusion = allIntStdElements_Incl;
                Metadata_Table.Internal_Standard_Value_Inclusion = allIntStdValues_Incl;
                Metadata_Table.Second_Internal_Standard_Element_Inclusion = allSecondIntStdElements_Incl;
                Metadata_Table.Second_Internal_Standard_Value_Inclusion = allSecondIntStdValues_Incl;
                Metadata_Table.Internal_Standard_Oxide_Value_Inclusion = allIntStdOxValues_Incl;
                Metadata_Table.Fe_Ratio_For_Total_Oxide_Inclusion = allFeRatios_Incl;
            end

            if isChecked("Host-Inclusion-unmixing")
                Metadata_Table.Mixing_Ratios = allMixingRatios;
                Metadata_Table.Unmixing_Via = allUnmixingVia;
                Metadata_Table.Matrix_Only_Tracer = allMatrixOnlyTracer;
                Metadata_Table.Matrix_From_Spot = allMatrixFromSpot;
            end

            if isChecked("Sensitivities")
                allsensTable = buildNumericBlock(allSensitivitiesRows, 'Sensitivities');
                allsensTable = renameAnalyteColumns(allsensTable, '_sens');
                if ismember('Data_Source', allsensTable.Properties.VariableNames)
                    allsensTable.Data_Source = [];
                end
                tableBlocks{end+1} = allsensTable;
            end

            if isChecked("Matrix/Host limits of detection") || isChecked("Matrix/Host mass fractions")
                allmatrixlodTable = buildNumericBlock(allmatrixlodRows, 'Matrix_LOD');
                allmatrixlodTable = renameAnalyteColumns(allmatrixlodTable, '_mat_lod');
                if ismember('Data_Source', allmatrixlodTable.Properties.VariableNames)
                    allmatrixlodTable.Data_Source = [];
                end
                tableBlocks{end+1} = allmatrixlodTable;
            end
            if isChecked("Matrix/Host mass fractions")
                allmatrixcompTable = buildNumericBlock(allmatrixcompRows, 'Matrix_Composition');
                allmatrixcompTable = renameAnalyteColumns(allmatrixcompTable, '_mat_ugg');
                if ismember('Data_Source', allmatrixcompTable.Properties.VariableNames)
                    allmatrixcompTable.Data_Source = [];
                end

                % Apply LOD filtering to matrix composition
                allmatrixcompTable = applyLODFiltering(allmatrixcompTable, allmatrixlodTable, '_mat_ugg', '_mat_lod');

                tableBlocks{end+1} = allmatrixcompTable;
            end

            if isChecked("Inclusion limits of detection") || isChecked("Inclusion mass fractions") || isChecked("Mix mass fractions")
                allinclusionlodTable = buildNumericBlock(allincllodRows, 'Inclusion_LOD');
                allinclusionlodTable = renameAnalyteColumns(allinclusionlodTable, '_incl_lod');
                if ismember('Data_Source', allinclusionlodTable.Properties.VariableNames)
                    allinclusionlodTable.Data_Source = [];
                end
                tableBlocks{end+1} = allinclusionlodTable;
            end

            if isChecked("Inclusion mass fractions")
                allinclusioncompTable = buildNumericBlock(allinclcompRows, 'Inclusion_Composition');
                allinclusioncompTable = renameAnalyteColumns(allinclusioncompTable, '_incl_ugg');
                if ismember('Data_Source', allinclusioncompTable.Properties.VariableNames)
                    allinclusioncompTable.Data_Source = [];
                end

                % Apply LOD filtering to inclusion composition
                allinclusioncompTable = applyLODFiltering(allinclusioncompTable, allinclusionlodTable, '_incl_ugg', '_incl_lod');

                tableBlocks{end+1} = allinclusioncompTable;
            end

            if isChecked("Mix mass fractions")
                allmixcompTable = buildNumericBlock(allmixcompRows, 'Mix_Composition');
                allmixcompTable = renameAnalyteColumns(allmixcompTable, '_mix_ugg');
                if ismember('Data_Source', allmixcompTable.Properties.VariableNames)
                    allmixcompTable.Data_Source = [];
                end

                % Apply LOD filtering to inclusion composition
                allmixcompTable = applyLODFiltering(allmixcompTable, allinclusionlodTable, '_mix_ugg', '_incl_lod');

                tableBlocks{end+1} = allmixcompTable;
            end
            if isChecked("Matrix/Host counts-per-sweep")
                allbkgdCpsTable_mat = buildNumericBlock(allbkgdCpsRows_mat, 'Bkgd_Corrected_CPS_mat');
                allbkgdCpsTable_mat = renameAnalyteColumns(allbkgdCpsTable_mat, '_mat_cps');
                if ismember('Data_Source', allbkgdCpsTable_mat.Properties.VariableNames)
                    allbkgdCpsTable_mat.Data_Source = [];
                end
                tableBlocks{end+1} = allbkgdCpsTable_mat;
            end
            if isChecked("Inclusion counts-per-sweep")
                allbkgdCpsTable_incl = buildNumericBlock(allbkgdCpsRows_incl, 'Bkgd_Corrected_CPS_incl');
                allbkgdCpsTable_incl = renameAnalyteColumns(allbkgdCpsTable_incl, '_incl_cps');
                if ismember('Data_Source', allbkgdCpsTable_incl.Properties.VariableNames)
                    allbkgdCpsTable_incl.Data_Source = [];
                end
                tableBlocks{end+1} = allbkgdCpsTable_incl;
            end
            if isChecked("Mix counts-per-sweep")
                allbkgdCpsTable_mix = buildNumericBlock(allbkgdCpsRows_mix, 'Bkgd_Corrected_CPS_mix');
                allbkgdCpsTable_mix = renameAnalyteColumns(allbkgdCpsTable_mix, '_mix_cps');
                if ismember('Data_Source', allbkgdCpsTable_mix.Properties.VariableNames)
                    allbkgdCpsTable_mix.Data_Source = [];
                end
                tableBlocks{end+1} = allbkgdCpsTable_mix;
            end
            if isChecked("LA parameters")
                %%%%%% Laser Parameters %%%%
                for i = 1:numel(allLaserParamTables)
                    if isempty(allLaserParamTables{i}) || height(allLaserParamTables{i}) == 0
                        nRows = height(allbkgdCpsRows_mat{i});
                        % Find a non-empty table to copy column info and types
                        for j = 1:numel(allLaserParamTables)
                            if ~isempty(allLaserParamTables{j}) && height(allLaserParamTables{j}) > 0
                                refTable = allLaserParamTables{j};
                                varNames = refTable.Properties.VariableNames;
                                varTypes = varfun(@class, refTable, 'OutputFormat', 'cell');
                                emptyData = cell(1, numel(varNames));
                                for k = 1:numel(varNames)
                                    switch varTypes{k}
                                        case {'double', 'single'}
                                            emptyData{k} = zeros(nRows,1);
                                        case {'cell'}
                                            emptyData{k} = cell(nRows,1);
                                        case {'char', 'string'}
                                            emptyData{k} = repmat({''}, nRows,1);
                                        case {'datetime'}
                                            emptyData{k} = NaT(nRows,1);
                                        case {'logical'}
                                            emptyData{k} = false(nRows,1);
                                        otherwise
                                            emptyData{k} = cell(nRows,1); % fallback
                                    end
                                end
                                allLaserParamTables{i} = table(emptyData{:}, 'VariableNames', varNames);
                                break;
                            end
                        end
                    end
                end

               % Harmonize columns across all laser parameter tables
                allLaserParamTables = harmonizeTableColumns(allLaserParamTables);

                % Now you can safely concatenate
                allLaserParamTables = vertcat(allLaserParamTables{:});
                tableBlocks{end+1} = allLaserParamTables;
            end
            if isChecked("ICP-MS parameters")
                %%%%%% ICP-MS Parameters %%%%

                % filepath: c:\Users\Sebastian\OneDrive - Universitaet Bern\PhD\Data\DataFrame\expandAndCombineICPMSParamTables.m
                for i = 1:numel(allICPMSParamTables)
                    nRows = height(allbkgdCpsRows_mat{i});
                    tbl = allICPMSParamTables{i};
                    if isempty(tbl) || height(tbl) == 0
                        % Find a non-empty reference table
                        for j = 1:numel(allICPMSParamTables)
                            if ~isempty(allICPMSParamTables{j}) && height(allICPMSParamTables{j}) > 0
                                refTable = allICPMSParamTables{j};
                                varNames = refTable.Properties.VariableNames;
                                emptyData = cell(1, numel(varNames));
                                for k = 1:numel(varNames)
                                    refCol = refTable.(varNames{k});
                                    refType = class(refCol);
                                    if strcmp(refType, 'cell')
                                        % Check the content type of the first non-empty cell
                                        firstNonEmpty = find(~cellfun(@isempty, refCol), 1);
                                        if isempty(firstNonEmpty)
                                            % Default to empty string cell
                                            emptyData{k} = repmat({''}, nRows, 1);
                                        else
                                            content = refCol{firstNonEmpty};
                                            if ischar(content) || isstring(content)
                                                emptyData{k} = repmat({''}, nRows, 1);
                                            elseif isnumeric(content)
                                                emptyData{k} = num2cell(zeros(nRows, 1));
                                            else
                                                emptyData{k} = cell(nRows, 1);
                                            end
                                        end
                                    elseif strcmp(refType, 'double') || strcmp(refType, 'single')
                                        emptyData{k} = zeros(nRows, 1);
                                    elseif strcmp(refType, 'char') || strcmp(refType, 'string')
                                        emptyData{k} = repmat({''}, nRows, 1);
                                    elseif strcmp(refType, 'datetime')
                                        emptyData{k} = NaT(nRows, 1);
                                    elseif strcmp(refType, 'logical')
                                        emptyData{k} = false(nRows, 1);
                                    else
                                        emptyData{k} = cell(nRows, 1); % fallback
                                    end
                                end
                                tbl = table(emptyData{:}, 'VariableNames', varNames);
                                break;
                            end
                        end
                    elseif height(tbl) == 1 && nRows > 1
                        tbl = tbl(ones(nRows,1), :);
                    end
                    allICPMSParamTables{i} = tbl;
                end

                % Harmonize columns across all laser parameter tables
                allICPMSParamTables = harmonizeTableColumns(allICPMSParamTables);

                allICPMSParamTables = vertcat(allICPMSParamTables{:});

                tableBlocks{end+1} = allICPMSParamTables;
            end

            % --- Concatenate only selected blocks ---
            Base_Table = [tableBlocks{:}];

            % --- Normalize some textual columns to cellstr for downstream compatibility ---
            if ~isempty(Base_Table)
                if ismember('Type', Base_Table.Properties.VariableNames)
                    Base_Table.Type = normalizeToCellstr(Base_Table.Type);
                end
                if ismember('Material', Base_Table.Properties.VariableNames)
                    Base_Table.Material = normalizeToCellstr(Base_Table.Material);
                end
                if ismember('Reference_Material', Base_Table.Properties.VariableNames)
                    Base_Table.Reference_Material = normalizeToCellstr(Base_Table.Reference_Material);
                end
                if ismember('Quantified_with', Base_Table.Properties.VariableNames)
                    Base_Table.Quantified_with = normalizeToCellstr(Base_Table.Quantified_with);
                end
                % Replace 0 with NaN in numeric columns only
                numericCols = varfun(@isnumeric, Base_Table, 'OutputFormat', 'uniform');
                if any(numericCols)
                    X = Base_Table{:, numericCols};
                    X(X == 0) = NaN;
                    Base_Table{:, numericCols} = X;
                end
            end


            % ----------------- Helpers -----------------
            function val = getNestedValue(C, b, p, k, defaultVal)
                % Safely extracts C{b,p}{k} allowing various shapes and types
                val = defaultVal;
                try
                    if iscell(C) && numel(C) >= (p-1)*size(C,1)+b && ~isempty(C)
                        X = C{b,p};
                        if iscell(X)
                            if numel(X) >= k && ~isempty(X{k})
                                val = X{k};
                            end
                        elseif isstring(X) || ischar(X)
                            if numel(X) >= k
                                val = X(k);
                            end
                        elseif isnumeric(X)
                            if numel(X) >= k
                                val = X(k);
                            end
                        end
                    end
                catch
                    val = defaultVal;
                end
            end

            function T = ensureAnalyteTable(T, analyteNames_, nRows_)
                % Ensure a table with nRows_ rows and analyte columns exists
                if ~istable(T) || isempty(T)
                    T = table('Size', [nRows_, numel(analyteNames_)], ...
                        'VariableTypes', repmat("double", 1, numel(analyteNames_)), ...
                        'VariableNames', cellstr(analyteNames_));
                    return
                end
                % Coerce non-double variables to double
                for v = 1:width(T)
                    if ~isnumeric(T{:,v})
                        try
                            T{:,v} = double(T{:,v});
                        catch
                            T{:,v} = zeros(height(T),1);
                        end
                    end
                end
                % Fix height
                if height(T) ~= nRows_
                    headers = T.Properties.VariableNames;
                    TT = table('Size', [nRows_, numel(headers)], ...
                        'VariableTypes', repmat("double", 1, numel(headers)), ...
                        'VariableNames', headers);
                    nCopy = min(height(T), nRows_);
                    if nCopy > 0
                        TT(1:nCopy, :) = T(1:nCopy, :);
                    end
                    T = TT;
                end
            end

            function sortedHeaders = getSortedHeaders(rowsCell)
                if isempty(rowsCell)
                    sortedHeaders = {};
                    return
                end
                isT = cellfun(@istable, rowsCell);
                if ~any(isT)
                    sortedHeaders = {};
                    return
                end
                headersCell = cellfun(@(t) t.Properties.VariableNames, rowsCell(isT), 'UniformOutput', false);
                if isempty(headersCell)
                    sortedHeaders = {};
                    return
                end
                allHeaders = unique([headersCell{:}], 'stable');
                nums = cellfun(@localExtractNum, allHeaders);
                [~, sortIdx] = sort(nums); % NaNs go last
                sortedHeaders = allHeaders(sortIdx);
            end

            function n = localExtractNum(h)
                tok = regexp(h, '\d+', 'match', 'once');
                if isempty(tok)
                    n = NaN;
                else
                    n = str2double(tok);
                end
            end

            function tableWithAllColumns = addMissingColumns(inputTable, targetHeaders)
                if isempty(targetHeaders)
                    tableWithAllColumns = inputTable;
                    return
                end
                missingCols = setdiff(targetHeaders, inputTable.Properties.VariableNames, 'stable');
                if isempty(missingCols)
                    tableWithAllColumns = inputTable(:, targetHeaders);
                    return
                end
                missingData = zeros(height(inputTable), numel(missingCols));
                missingTable = array2table(missingData, 'VariableNames', missingCols);
                tableWithAllColumns = [inputTable missingTable];
                tableWithAllColumns = tableWithAllColumns(:, targetHeaders);
            end

            function outTable = buildNumericBlock(rowsCell, label)
                if isempty(rowsCell)
                    outTable = table();
                    return
                end
                headers = getSortedHeaders(rowsCell);
                if ~isempty(rowsCell)
                    rowsCell = cellfun(@(t) addMissingColumns(t, headers), rowsCell, 'UniformOutput', false);
                end
                if isempty(rowsCell)
                    outTable = table();
                    return
                end
                outTable = vertcat(rowsCell{:});
                if isempty(outTable)
                    return
                end
                outTable = addvars(outTable, repmat({label}, height(outTable), 1), ...
                    'Before', 1, 'NewVariableNames', 'Data_Source');
            end

            function T = renameAnalyteColumns(T, suffix)
                if isempty(T)
                    return
                end
                metaCols = {'Data_Source'};
                for v = 1:width(T)
                    if ~ismember(T.Properties.VariableNames{v}, metaCols)
                        T.Properties.VariableNames{v} = [T.Properties.VariableNames{v} suffix];
                    end
                end
            end

            function T = normalizeToCellstr(T)
                if istable(T) || isstruct(T)
                    return
                end
                if iscell(T)
                    try
                        T = cellfun(@char, T, 'UniformOutput', false);
                    catch
                        T = cellfun(@(x) char(string(x)), T, 'UniformOutput', false);
                    end
                else
                    T = cellstr(string(T));
                end
            end

            function C = getNestedField(spotStruct, fieldName, uniqueBracketIds, unique_PRMs, nSpots, defaultVal)
                if isfield(spotStruct, fieldName)
                    C = spotStruct.(fieldName);
                else
                    C = cell(numel(uniqueBracketIds), numel(unique_PRMs));
                    for b = 1:numel(uniqueBracketIds)
                        for p = 1:numel(unique_PRMs)
                            if ischar(defaultVal) || isstring(defaultVal)
                                C{b, p} = repmat({defaultVal}, nSpots, 1);
                            else
                                C{b, p} = repmat(defaultVal, nSpots, 1);
                            end
                        end
                    end
                end
            end

            function C = getNestedTable(spotStruct, fieldName, uniqueBracketIds, unique_PRMs, nSpots, analyteNames)
                if isfield(spotStruct, fieldName)
                    C = spotStruct.(fieldName);
                else
                    C = cell(numel(uniqueBracketIds), numel(unique_PRMs));
                    for b = 1:numel(uniqueBracketIds)
                        for p = 1:numel(unique_PRMs)
                            C{b, p} = table('Size', [nSpots, numel(analyteNames)], ...
                                'VariableTypes', repmat("double", 1, numel(analyteNames)), ...
                                'VariableNames', cellstr(analyteNames));
                        end
                    end
                end
            end

            function C = getNestedMixingRatio(spotStruct, fieldName, uniqueBracketIds, unique_PRMs, nSpots)
                if isfield(spotStruct, fieldName)
                    C = spotStruct.(fieldName);
                else
                    % Assume 3 mixing ratios per bracket/PRM, fill with NaN
                    C = cell(numel(uniqueBracketIds), numel(unique_PRMs));
                    for b = 1:numel(uniqueBracketIds)
                        for p = 1:numel(unique_PRMs)
                            C{b, p} = repmat({NaN}, nSpots, 1);
                        end
                    end
                end
            end

            function val = getNestedMixingRatioValue(C, b, p, k)
                % Returns a cell array of mixing ratios for spot k, bracket b, PRM p
                val = [];
                try
                    if iscell(C) && numel(C) >= (p-1)*size(C,1)+b && ~isempty(C)
                        X = C{b,p};
                        if iscell(X)
                            if numel(X) >= k && ~isempty(X{k})
                                val = X{k};
                            else
                                val = NaN;
                            end
                        elseif isnumeric(X)
                            if numel(X) >= k
                                val = X(k);
                            else
                                val = NaN;
                            end
                        end
                    end
                catch
                    val = NaN;
                end
            end

            function T = struct_to_table(S)
                % Converts a struct S with fields of arrays/cells/chars to a table.
                % Singleton fields are duplicated to match the length of the longest field.
                if isempty(S)
                    T = table();
                    return
                end
                fields = fieldnames(S);
                lens = zeros(numel(fields),1);
                for o = 1:numel(fields)
                    col = S.(fields{o});
                    if ischar(col) || (isstring(col) && isscalar(col))
                        lens(o) = 1;
                    else
                        lens(o) = numel(col);
                    end
                end
                n = max(lens);
                T = table();
                for o = 1:numel(fields)
                    col = S.(fields{o});
                    % Handle empty fields
                    if isempty(col)
                        % Guess type from field name or just use NaN
                        col = nan(n,1);
                        % Handle char arrays and string scalars
                    elseif ischar(col) || (isstring(col) && isscalar(col))
                        col = repmat({col}, n, 1);
                    elseif iscell(col) && numel(col) == 1 && n > 1
                        col = repmat(col, n, 1);
                    elseif isnumeric(col) && numel(col) == 1 && n > 1
                        col = repmat(col, n, 1);
                    elseif numel(col) ~= n
                        error('Field %s has %d elements, expected %d.', fields{o}, numel(col), n);
                    end
                    % Ensure column is column vector
                    if isrow(col) && ~ischar(col)
                        col = col';
                    end
                    T.(fields{o}) = col;
                end
            end

            function harmonizedTables = harmonizeTableColumns(tableCell)
                % Collects all unique column names across all tables
                % and ensures each table has all columns (filling missing with NaN)

                if isempty(tableCell)
                    harmonizedTables = tableCell;
                    return
                end

                % Collect all unique variable names
                allVarNames = {};
                for i = 1:numel(tableCell)
                    if ~isempty(tableCell{i}) && istable(tableCell{i})
                        allVarNames = [allVarNames, tableCell{i}.Properties.VariableNames];
                    end
                end
                allVarNames = unique(allVarNames, 'stable');

                % Add missing columns to each table
                for i = 1:numel(tableCell)
                    tbl = tableCell{i};
                    if isempty(tbl) || ~istable(tbl)
                        continue
                    end

                    existingVars = tbl.Properties.VariableNames;
                    missingVars = setdiff(allVarNames, existingVars, 'stable');

                    for j = 1:numel(missingVars)
                        % Add missing column filled with NaN or empty cells
                        varName = missingVars{j};
                        nRows = height(tbl);

                        % Try to infer type from other tables
                        varType = 'double'; % default
                        for k = 1:numel(tableCell)
                            if ~isempty(tableCell{k}) && istable(tableCell{k}) && ...
                                    ismember(varName, tableCell{k}.Properties.VariableNames)
                                refCol = tableCell{k}.(varName);
                                varType = class(refCol);
                                break;
                            end
                        end

                        % Create appropriate empty column
                        switch varType
                            case {'double', 'single'}
                                tbl.(varName) = nan(nRows, 1);
                            case 'cell'
                                tbl.(varName) = cell(nRows, 1);
                            case {'char', 'string'}
                                tbl.(varName) = repmat({''}, nRows, 1);
                            case 'datetime'
                                tbl.(varName) = NaT(nRows, 1);
                            case 'logical'
                                tbl.(varName) = false(nRows, 1);
                            otherwise
                                tbl.(varName) = cell(nRows, 1);
                        end
                    end

                    % Reorder columns to match allVarNames
                    tbl = tbl(:, allVarNames);
                    tableCell{i} = tbl;
                end

                harmonizedTables = tableCell;
            end

            function filteredCompTable = applyLODFiltering(compTable, lodTable, compSuffix, lodSuffix)
                % Apply LOD filtering: replace values below LOD with formatted strings like '<0.123'

                filteredCompTable = compTable;

                % Get composition and LOD column names (excluding metadata)
                compCols = compTable.Properties.VariableNames;
                compCols = compCols(contains(compCols, compSuffix));

                lodCols = lodTable.Properties.VariableNames;
                lodCols = lodCols(contains(lodCols, lodSuffix));

                % Match corresponding columns
                for l = 1:numel(compCols)
                    % Extract element name
                    elementName = strrep(compCols{l}, compSuffix, '');
                    lodColName = [elementName, lodSuffix];

                    if ismember(lodColName, lodCols)
                        % Get arrays
                        comp_array = compTable.(compCols{l});
                        lod_array = lodTable.(lodColName);

                        % Create logical mask for values below detection limit
                        below_lod = comp_array < lod_array;

                        % Create cell array for display values
                        display_values = num2cell(comp_array);

                        % Format detection limit values where needed
                        display_values(below_lod) = arrayfun(@(x) ['<' num2str(x)], ...
                            lod_array(below_lod), 'UniformOutput', false);

                        % Update filtered table
                        filteredCompTable.(compCols{l}) = display_values;
                    end
                end
            end

        end


    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, source)

            app.base = source.base;

            update_project_session_tree(app)

            SelectionModeDropDownValueChanged(app)

        end

        % Button pushed function: ExportTableButton
        function ExportTableButtonPushed(app, event)
            

            progress = uiprogressdlg(app.TableBuilderUIFigure, 'Title', 'ICP-Base', ...
                'Message', 'Building table...', 'Cancelable', 'off', 'Indeterminate','on');

            try
                Base_Table = build_table_from_base(app);
            catch

                message = "Table building failed";
                uialert(app.TableBuilderUIFigure,message,"Warning", ...
                    "Icon","warning");
            end

            if isempty(Base_Table)
                message = "Exporting failed. No table was built.";
                uialert(app.TableBuilderUIFigure,message,"Warning", ...
                    "Icon","warning");
                return
            end

            delete(progress)

            fig = app.TableBuilderUIFigure;
            msg = "Be aware that the mass fractions µg/g are not limit of detection (LOD) filtered. If you intent to use the µg/g data, filter the values to disregard all mass fractions below LOD.";
            title = "ICP-Base";
            selection = uiconfirm(fig,msg,title, ...
                "Options",["I will apply LOD filtering before I use the µg/g data","Cancel"], ...
                "DefaultOption",2,"CancelOption",2);

            switch selection
                case 'I will apply LOD filtering before I use the µg/g data'

                    [filename, pathname] = uiputfile('*.xlsx', 'Save Excel file');
                    outputpath = fullfile(pathname, filename);
                    if isequal(filename, 0) || isequal(pathname, 0)
                        return; % User canceled file selection
                    end

                    % Write the table to Excel
                    writetable(Base_Table, outputpath);

                case 'Cancel'
                    return
            end


        end

        % Button pushed function: ProceedtoVisModuleButton
        function ProceedtoVisModuleButtonPushed(app, event)


            progress = uiprogressdlg(app.TableBuilderUIFigure, 'Title', 'ICP-Base', ...
                'Message', 'Building table...', 'Cancelable', 'off', 'Indeterminate','on');

            %try

                Base_Table = build_table_from_base(app);
            %catch
                
                %message = "Table building failed";
                %uialert(app.TableBuilderUIFigure,message,"Warning", ...
                    %"Icon","warning");
            %end

            if isempty(Base_Table)
                message = "Proceeding to the VisModule failed. No table was built.";
                uialert(app.TableBuilderUIFigure,message,"Warning", ...
                    "Icon","warning");
                return
            end

            VisModule(Base_Table);
            delete(progress)
        end

        % Callback function: ProjectSessionTree
        function ProjectSessionTreeCheckedNodesChanged(app, event)


            update_spot_meta_tree(app)
        end

        % Value changed function: SelectionModeDropDown
        function SelectionModeDropDownValueChanged(app, event)
            value = app.SelectionModeDropDown.Value;

            if isequal(value, 'Simple')

                app.SimplePanel.Visible = true;
                app.AdvancedPanel.Visible = false;

            elseif isequal(value, 'Advanced')
                
                app.SimplePanel.Visible = false;
                app.AdvancedPanel.Visible = true;

                update_spot_meta_tree(app)
            end

        end

        % Value changed function: SimpleDropDown
        function SimpleDropDownValueChanged(app, event)

            if isequal(app.SelectionModeDropDown.Value, 'Simple') && isequal(app.SimpleDropDown.Value, 'Mass fractions (LOD filtered) (Masses)')


            end


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.TableBuilderUIFigure = uifigure('Visible', 'off');
            app.TableBuilderUIFigure.Name = 'Table Builder';
            app.TableBuilderUIFigure.Icon = 'icon_48.png';
            app.TabGroup = uitabgroup(app.TableBuilderUIFigure);
            app.DataselectionTab = uitab(app.TabGroup);
            app.DataselectionTab.Title = 'Data selection';
            app.ProjectSessionTree = uitree(app.DataselectionTab, 'checkbox');
            % Assign Checked Nodes
            app.ProjectSessionTree.CheckedNodesChangedFcn = createCallbackFcn(app, @ProjectSessionTreeCheckedNodesChanged, true);
            app.ExportTableButton = uibutton(app.DataselectionTab, 'push');
            app.ExportTableButton.ButtonPushedFcn = createCallbackFcn(app, @ExportTableButtonPushed, true);
            app.ExportTableButton.Text = 'Export Table';
            app.ProceedtoVisModuleButton = uibutton(app.DataselectionTab, 'push');
            app.ProceedtoVisModuleButton.ButtonPushedFcn = createCallbackFcn(app, @ProceedtoVisModuleButtonPushed, true);
            app.ProceedtoVisModuleButton.Text = 'Proceed to VisModule';
            app.SelectionModeDropDownLabel = uilabel(app.DataselectionTab);
            app.SelectionModeDropDownLabel.Text = 'Selection Mode:';
            app.SelectionModeDropDown = uidropdown(app.DataselectionTab);
            app.SelectionModeDropDown.Items = {'Simple', 'Advanced'};
            app.SelectionModeDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectionModeDropDownValueChanged, true);
            app.SelectionModeDropDown.Value = 'Advanced';
            app.AdvancedPanel = uipanel(app.DataselectionTab);
            app.SpotMetaTree = uitree(app.AdvancedPanel, 'checkbox');
            app.SimplePanel = uipanel(app.DataselectionTab);
            app.SimpleDropDown = uidropdown(app.SimplePanel);
            app.SimpleDropDown.Items = {'Mass fractions (LOD filtered) (Masses)'};
            app.SimpleDropDown.ValueChangedFcn = createCallbackFcn(app, @SimpleDropDownValueChanged, true);
            app.SimpleDropDown.Value = 'Mass fractions (LOD filtered) (Masses)';
            % Show the figure after all components are created
            app.TableBuilderUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Table_builder_Submodule(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.TableBuilderUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.TableBuilderUIFigure)
        end
    end
end