classdef CombineColHelper < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        CombineColModuleUIFigure  matlab.ui.Figure
        GridLayout                matlab.ui.container.GridLayout
        AddButton                 matlab.ui.control.Button
        TextArea_2                matlab.ui.control.TextArea
        CheckButton               matlab.ui.control.Button
        UITable                   matlab.ui.control.Table
        TextArea                  matlab.ui.control.TextArea
        ListBox                   matlab.ui.control.ListBox
    end

    
    properties (Access = private)
        mainapp % Description
       T
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, T)
            app.mainapp=mainapp; 
            app.T=T;
            app.ListBox.Items=app.T.ColumnName;

        end

        % Value changed function: ListBox
        function ListBoxValueChanged(app, event)

            if iscell(app.TextArea.Value)
                app.TextArea.Value=[[app.TextArea.Value{:}] ' ' app.ListBox.Value ];
            else
                app.TextArea.Value=[app.TextArea.Value ' ' app.ListBox.Value ];
            end


        end

        % Button pushed function: CheckButton
        function CheckButtonPushed(app, event)
            app.TextArea_2.Value=app.TextArea.Value;

            test=split(app.TextArea.Value,' ');
            test=test(not(cellfun(@isempty,test))&ismember(test,app.ListBox.Items));

            for n=1:numel(test)
                thx(:,n)=app.T.Data.(char(test(n)));
            end

            if iscell(thx)
                app.UITable.Data = cell2table(thx);
            else
                app.UITable.Data = array2table(thx);
            end
            app.UITable.ColumnName=test;


        end

        % Value changed function: TextArea
        function TextAreaValueChanged(app, event)
            value = app.TextArea.Value;
        end

        % Button pushed function: AddButton
        function AddButtonPushed(app, event)
            try
                app.mainapp.d = uiprogressdlg(app.mainapp.VisModule_GUI,'Title','Updating Table', ...
                'Message','Updating Table','Cancelable','off');

                thx=table2cell(app.UITable.Data);
                thx(cellfun(@isempty,thx))={''};
                thx=join(string(thx));
                thx=cellstr(thx);
                [app.T.Data.(char(matlab.lang.makeValidName(app.TextArea_2.Value)))]=thx(:);
                app.T.ColumnName=app.T.Data.Properties.VariableNames;
                %  app.mainapp.update_public(1)
                app.mainapp.update_public_fun

                delete(app.mainapp.d)

                delete(app)
            catch ME
                CheckButtonPushed(app)
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.CombineColModuleUIFigure = uifigure('Visible', 'off');
            app.CombineColModuleUIFigure.Name = 'CombineColModule';
            app.GridLayout = uigridlayout(app.CombineColModuleUIFigure);
            app.ListBox = uilistbox(app.GridLayout);
            app.ListBox.Items = {};
            app.ListBox.ValueChangedFcn = createCallbackFcn(app, @ListBoxValueChanged, true);
            app.ListBox.Value = {};
            app.TextArea = uitextarea(app.GridLayout);
            app.TextArea.ValueChangedFcn = createCallbackFcn(app, @TextAreaValueChanged, true);
            app.TextArea.Placeholder = 'Columns to combine';
            app.UITable = uitable(app.GridLayout);
            app.UITable.ColumnName = '';
            app.UITable.RowName = {};
            app.CheckButton = uibutton(app.GridLayout, 'push');
            app.CheckButton.ButtonPushedFcn = createCallbackFcn(app, @CheckButtonPushed, true);
            app.CheckButton.Text = 'Check';
            app.TextArea_2 = uitextarea(app.GridLayout);
            app.TextArea_2.Placeholder = 'New Column Name';
            app.AddButton = uibutton(app.GridLayout, 'push');
            app.AddButton.ButtonPushedFcn = createCallbackFcn(app, @AddButtonPushed, true);
            app.AddButton.Text = 'Add';
            % Show the figure after all components are created
            app.CombineColModuleUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CombineColHelper(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.CombineColModuleUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.CombineColModuleUIFigure)
        end
    end
end