classdef EasyPlotModule < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        EasyPlotModuleUIFigure          matlab.ui.Figure
        ylinlogButton                   matlab.ui.control.Button
        ygridButton                     matlab.ui.control.Button
        xgridButton                     matlab.ui.control.Button
        xlinlogButton                   matlab.ui.control.Button
        GridLayout2                     matlab.ui.container.GridLayout
        FindPlotButton                  matlab.ui.control.Button
        CloseallfiguresButton           matlab.ui.control.Button
        UpdateListButton                matlab.ui.control.Button
        Tree                            matlab.ui.container.Tree
        TabGroup                        matlab.ui.container.TabGroup
        PlotSettingsTab                 matlab.ui.container.Tab
        GridLayout5                     matlab.ui.container.GridLayout
        TabGroup2                       matlab.ui.container.TabGroup
        DataSettingsTab                 matlab.ui.container.Tab
        GridLayout4                     matlab.ui.container.GridLayout
        MultiPlotButton                 matlab.ui.control.Button
        LegendInterpreterDropDown       matlab.ui.control.DropDown
        LegendInterpreterDropDownLabel  matlab.ui.control.Label
        ReplaceStringinNameButton       matlab.ui.control.Button
        ReadStyleButton                 matlab.ui.control.Button
        ExportStyleButton               matlab.ui.control.Button
        ApplyGlobalButton               matlab.ui.control.Button
        ApplyGlobaltoselectionButton    matlab.ui.control.Button
        MergeSelectionButton            matlab.ui.control.Button
        MoveUpButton                    matlab.ui.control.Button
        FlipAllButton                   matlab.ui.control.Button
        MoveDownButton                  matlab.ui.control.Button
        FlipSelectionButton             matlab.ui.control.Button
        ColorPreviewCheckBox            matlab.ui.control.CheckBox
        AdvancedSettingsCheckBox        matlab.ui.control.CheckBox
        ShowLegendCheckBox              matlab.ui.control.CheckBox
        RemoveSelectedButton            matlab.ui.control.Button
        MultiCor_table                  matlab.ui.control.Table
        FigureSettingsTab               matlab.ui.container.Tab
        GridLayout6                     matlab.ui.container.GridLayout
        ZLimitsEditField_min            matlab.ui.control.NumericEditField
        ZLimitsEditFieldLabel           matlab.ui.control.Label
        ZLimitsEditField_max            matlab.ui.control.NumericEditField
        XLimitsEditField_max            matlab.ui.control.NumericEditField
        YLimitsEditField_max            matlab.ui.control.NumericEditField
        XLimitsEditField_min            matlab.ui.control.NumericEditField
        XLimitsEditFieldLabel           matlab.ui.control.Label
        YLimitsEditField_min            matlab.ui.control.NumericEditField
        YLimitsEditFieldLabel           matlab.ui.control.Label
        ZGridDropDown                   matlab.ui.control.DropDown
        ZGridDropDownLabel              matlab.ui.control.Label
        YGridDropDown                   matlab.ui.control.DropDown
        YGridDropDownLabel              matlab.ui.control.Label
        XGridDropDown                   matlab.ui.control.DropDown
        XGridDropDownLabel              matlab.ui.control.Label
        ZScaleDropDown                  matlab.ui.control.DropDown
        ZScaleDropDownLabel             matlab.ui.control.Label
        YScaleDropDown                  matlab.ui.control.DropDown
        YScaleDropDownLabel             matlab.ui.control.Label
        XScaleDropDown                  matlab.ui.control.DropDown
        XScaleDropDownLabel             matlab.ui.control.Label
        CustomAxLimitsCheckBox          matlab.ui.control.CheckBox
        ZDirDropDown                    matlab.ui.control.DropDown
        ZDirDropDownLabel               matlab.ui.control.Label
        YDirDropDown                    matlab.ui.control.DropDown
        YDirDropDownLabel               matlab.ui.control.Label
        XDirDropDown                    matlab.ui.control.DropDown
        XDirDropDownLabel               matlab.ui.control.Label
        YAxesPosDropDown                matlab.ui.control.DropDown
        YAxesPosDropDownLabel           matlab.ui.control.Label
        XAxesPosDropDown                matlab.ui.control.DropDown
        XAxesPosDropDownLabel           matlab.ui.control.Label
        FontDropDown                    matlab.ui.control.DropDown
        FontDropDownLabel               matlab.ui.control.Label
        XLabelInterpreterDropDown       matlab.ui.control.DropDown
        XLabelInterpreterDropDownLabel  matlab.ui.control.Label
        ColorbarLabelEditField          matlab.ui.control.EditField
        ColorbarLabelEditField_Label    matlab.ui.control.Label
        ZLabelEditField                 matlab.ui.control.EditField
        YLabelEditField_2Label          matlab.ui.control.Label
        YLabelEditField                 matlab.ui.control.EditField
        YLabelEditFieldLabel            matlab.ui.control.Label
        XLabelEditField                 matlab.ui.control.EditField
        XLabelEditFieldLabel            matlab.ui.control.Label
        FigureFontSizeSpinner           matlab.ui.control.Spinner
        FigureFontSizeSpinnerLabel      matlab.ui.control.Label
        ColorbarLabelInterpreterDropDown  matlab.ui.control.DropDown
        ColorbarLabelInterpreterDropDown_Label  matlab.ui.control.Label
        ZLabelInterpreterDropDown       matlab.ui.control.DropDown
        ZLabelInterpreterDropDown_Label  matlab.ui.control.Label
        YLabelInterpreterDropDown       matlab.ui.control.DropDown
        YLabelInterpreterDropDown_Label  matlab.ui.control.Label
        InspectAllPropertiesButton      matlab.ui.control.Button
        LegendTab                       matlab.ui.container.Tab
        GridLayout7                     matlab.ui.container.GridLayout
        LegendMarkerSizeSpinner         matlab.ui.control.Spinner
        LegendMarkerSizeSpinnerLabel    matlab.ui.control.Label
        NumberofcolumnsSpinner          matlab.ui.control.Spinner
        NumberofcolumnsSpinnerLabel     matlab.ui.control.Label
        OrientationDropDown             matlab.ui.control.DropDown
        OrientationDropDownLabel        matlab.ui.control.Label
        LocationDropDown                matlab.ui.control.DropDown
        LocationDropDownLabel           matlab.ui.control.Label
        ShowLegendCheckBox_2            matlab.ui.control.CheckBox
        AutoButton                      matlab.ui.control.Button
        ExportTab                       matlab.ui.container.Tab
        GridLayout3                     matlab.ui.container.GridLayout
        CustStrEditField                matlab.ui.control.EditField
        CustomStringLabel               matlab.ui.control.Label
        CheckBox_fig_exp                matlab.ui.control.CheckBox
        CheckBox_jpg_exp                matlab.ui.control.CheckBox
        ExportSelectionButton           matlab.ui.control.Button
        ExportSelectedButton            matlab.ui.control.Button
        CheckBox_tif_exp                matlab.ui.control.CheckBox
        CheckBox_eps_exp                matlab.ui.control.CheckBox
        CheckBox_pdf_exp                matlab.ui.control.CheckBox
        CheckBox_png_exp                matlab.ui.control.CheckBox
        ExportDirectoryEditField        matlab.ui.control.EditField
        ExportDirectoryEditFieldLabel   matlab.ui.control.Label
        SelectDirButton                 matlab.ui.control.Button
        ExportAllButton                 matlab.ui.control.Button
    end


    properties (Access = private)
        active_plot % Description
        click_time % Description
    end

    properties (Access = public)
        mainapp % Description
        MinPlotX
    end

    methods (Access = private)

        function ResetTree(app,Tree2Update)
            if not(exist('Tree2Update','var'))
                Tree2Update = app.Tree;
            end

            %             if ~isempty(Tree2Update.SelectedNodes)
            %                 app.SelectedNodes_MainTree = Tree2Update.SelectedNodes.NodeData;
            %             end

            a = Tree2Update.Children;
            a.delete;
        end


        function update_ScatterObject_table(app)
            T = table();


            if isempty(app.MinPlotX.MinPlotXFigures.hFig)%;(app.active_plot)
                app.MultiCor_table.Data = T;
                return
            end


            children = app.MinPlotX.MinPlotXFigures.hFig(app.active_plot).ax2plot.Children;
            scatter_obj = flipud(children(strcmp(get(children,'Type'),'scatter')));


            [line_obj,all_lines,tags,tagIdx ] = get_line_objects(app,app.MinPlotX.MinPlotXFigures.hFig(app.active_plot).ax2plot);


            if isempty(scatter_obj) && isempty(line_obj)
                app.MultiCor_table.Data = T;
                return
            end


            try
                if isempty(scatter_obj)
                    Name = {line_obj.DisplayName}';%app.MinPlotX.MinPlotXFigures.hFig(app.active_plot).legend_str';
                    Active = cellfun(@logical,{line_obj.Visible}');
                    MarkerFaceColor = convert_col2str(app,{line_obj.MarkerFaceColor}');
                    Color = convert_col2str(app,{line_obj.Color}');%cell2mat({line_obj.Color}');
                    Symbol = {line_obj.Marker}';
                    LineStyle = {line_obj.LineStyle}';
                    SymbolSize = {line_obj.MarkerSize}';
                    LineWidth = {line_obj.LineWidth}';

                    %  MarkerEdgeColor = cell2mat({line_obj.MarkerEdgeColor}');

                    MarkerEdgeColor = convert_col2str(app,{line_obj.MarkerEdgeColor}');%cell2mat({line_obj.MarkerEdgeColor}');

                    LineAlpha = ones(size(LineWidth));

                    for n_SID=1:numel(line_obj)
                        if isfield(line_obj(n_SID).UserData,'LineAlpha')
                            LineAlpha(n_SID)=line_obj(n_SID).UserData.LineAlpha;
                        else
                            LineAlpha(n_SID) = 1;
                        end
                    end


                    if app.AdvancedSettingsCheckBox.Value == true

                        T = table(Active,Name,Color,LineStyle,LineWidth,LineAlpha,Symbol,SymbolSize,MarkerFaceColor,MarkerEdgeColor);%here
                    else

                        T = table(Active,Name,Color,LineStyle,LineWidth,Symbol,SymbolSize,MarkerFaceColor);%here
                    end

                    if app.AdvancedSettingsCheckBox.Value == true
                        T.Properties.VariableNames = {'Active'; 'Name';'Color';'LineStyle' ;'LineWidth';'LineAlpha'; 'Symbol'; 'SymbolSize';'MarkerFaceColor';'MarkerEdgeColor'};
                        c_editable = [true,true,true,true,true,true,true,true,true,true,true];
                    else
                        T.Properties.VariableNames = {'Active'; 'Name';'Color' ;'LineStyle' ;'LineWidth'; 'Symbol'; 'SymbolSize';'MarkerFaceColor'};
                        c_editable = [true,true,true,true,true,true true true];
                    end


                elseif isempty(line_obj)

                    Name = {scatter_obj.DisplayName}';
                    Active = cellfun(@logical,{scatter_obj.Visible}');

                    Symbol = {scatter_obj.Marker}';
                    SymbolSize = cell2mat({scatter_obj.SizeData}');

                    MarkerEdgeColor = convert_col2str(app,{scatter_obj.MarkerEdgeColor}');
                    %   MarkerEdgeColor = cell2mat({scatter_obj.MarkerEdgeColor}');
                    MarkerEdgeAlpha = cell2mat({scatter_obj.MarkerEdgeAlpha}');

                    MarkerFaceColor = convert_col2str(app,{scatter_obj.MarkerFaceColor}');
                    %   MarkerFaceColor = {scatter_obj.MarkerFaceColor}';
                    MarkerFaceAlpha = cell2mat({scatter_obj.MarkerFaceAlpha}');

                    LineWidth = cell2mat({scatter_obj.LineWidth}');
                    LineStyle = cell(size(LineWidth));
                    LineStyle(1:size(LineWidth,1),1) = {'none'};
                    % Color(1:size(LineWidth,1),1) = {''};
                    if app.AdvancedSettingsCheckBox.Value == true

                        T = table(Active,Name,Symbol,SymbolSize,MarkerFaceColor,MarkerFaceAlpha,MarkerEdgeColor,MarkerEdgeAlpha,LineStyle,LineWidth);%here
                    else

                        T = table(Active,Name,Symbol,SymbolSize,MarkerFaceColor,LineStyle,LineWidth);%here
                    end


                    if app.AdvancedSettingsCheckBox.Value == true
                        T.Properties.VariableNames = {'Active'; 'Name'; 'Symbol'; 'SymbolSize';'MarkerFaceColor';'MarkerFaceAlpha';'MarkerEdgeColor';'MarkerEdgeAlpha';'LineStyle' ;'LineWidth';};
                        c_editable = [true,true,true,true,true,true,true,true,true,true,true];
                    else
                        T.Properties.VariableNames = {'Active'; 'Name'; 'Symbol'; 'SymbolSize';'MarkerFaceColor';'LineStyle' ;'LineWidth'};
                        c_editable = [true,true,true,true,true,true true true];
                    end


                end


                app.MultiCor_table.Data = (T);

                app.MultiCor_table.ColumnName = T.Properties.VariableNames;
                app.MultiCor_table.ColumnEditable = c_editable;

            catch ME
                DisplayError(ME,'EasyPlotModule Compile Table Error')
            end


            if app.ColorPreviewCheckBox.Value == true && not(isempty(T))

                s = uistyle('BackgroundColor',[1 1 1 ]);
                addStyle(app.MultiCor_table,s);

                for n = 1:numel(app.MultiCor_table.Data.Active)
                    if isnumeric(app.MultiCor_table.Data.MarkerFaceColor(n,:))
                        s = uistyle('BackgroundColor',app.MultiCor_table.Data.MarkerFaceColor(n,:));
                        addStyle(app.MultiCor_table,s,'cell',[n,find(strcmp(app.MultiCor_table.ColumnName, 'MarkerFaceColor'))])
                    elseif ~isnumeric((app.MultiCor_table.Data.MarkerFaceColor(n,:))) && startsWith(app.MultiCor_table.Data.MarkerFaceColor(n,:), "[")
                        s = uistyle('BackgroundColor',sscanf(app.MultiCor_table.Data.MarkerFaceColor{n,:}, '[%f %f %f %f %f %f %f %f %f %f]'));
                        addStyle(app.MultiCor_table,s,'cell',[n,find(strcmp(app.MultiCor_table.ColumnName, 'MarkerFaceColor'))])

                    elseif strcmp(app.MultiCor_table.Data.MarkerFaceColor(n,:),'auto')
                        s = uistyle('BackgroundColor',app.MultiCor_table.Data.Color(n,:));
                        addStyle(app.MultiCor_table,s,'cell',[n,find(strcmp(app.MultiCor_table.ColumnName, 'MarkerFaceColor'))])

                    end
                    %                     if isnumeric(app.MultiCor_table.Data.MarkerFaceColor(n,:))
                    %                         s = uistyle('BackgroundColor',app.MultiCor_table.Data.MarkerFaceColor(n,:));
                    %                         addStyle(app.MultiCor_table,s,'cell',[n,find(strcmp(app.MultiCor_table.ColumnName, 'MarkerFaceColor'))])
                    %                         %                     else
                    %                         %                         s = uistyle('BackgroundColor',[1 1 1 ]);
                    %                         %                         if n == 6
                    %                         %                      0
                    %                         %                           end
                    %                         %                         addStyle(app.MultiCor_table,s,'cell',[n,find(strcmp(app.MultiCor_table.ColumnName, 'MarkerFaceColor'))])
                    %                     end

                    if any(strcmp(app.MultiCor_table.ColumnName,'Color'))&& isnumeric(app.MultiCor_table.Data.Color(n,:)) && isempty(scatter_obj)
                        s = uistyle('BackgroundColor',app.MultiCor_table.Data.Color(n,:));
                        addStyle(app.MultiCor_table,s,'cell',[n,find(strcmp(app.MultiCor_table.ColumnName,'Color'))])
                    elseif any(strcmp(app.MultiCor_table.ColumnName,'Color'))&& ~isnumeric((app.MultiCor_table.Data.Color(n,:))) && startsWith(app.MultiCor_table.Data.Color(n,:), "[")
                        s = uistyle('BackgroundColor',sscanf(app.MultiCor_table.Data.Color{n,:}, '[%f %f %f %f %f %f %f %f %f %f]'));
                        addStyle(app.MultiCor_table,s,'cell',[n,find(strcmp(app.MultiCor_table.ColumnName, 'Color'))])
                    end

                    if app.AdvancedSettingsCheckBox.Value == true


                        if isnumeric(app.MultiCor_table.Data.MarkerEdgeColor(n,:))
                            s = uistyle('BackgroundColor',app.MultiCor_table.Data.MarkerEdgeColor(n,:));
                            addStyle(app.MultiCor_table,s,'cell',[n,find(strcmp(app.MultiCor_table.ColumnName, 'MarkerEdgeColor'))])
                        elseif ~isnumeric((app.MultiCor_table.Data.MarkerEdgeColor(n,:))) && startsWith(app.MultiCor_table.Data.MarkerEdgeColor(n,:), "[")
                            s = uistyle('BackgroundColor',sscanf(app.MultiCor_table.Data.MarkerEdgeColor{n,:}, '[%f %f %f %f %f %f %f %f %f %f]'));
                            addStyle(app.MultiCor_table,s,'cell',[n,find(strcmp(app.MultiCor_table.ColumnName, 'MarkerEdgeColor'))])

                        elseif strcmp(app.MultiCor_table.Data.MarkerEdgeColor(n,:),'auto')
                            if any(strcmp(app.MultiCor_table.ColumnName,'Color'))&& isnumeric(app.MultiCor_table.Data.Color(n,:)) && isempty(scatter_obj)
                                c=app.MultiCor_table.Data.Color(n,:);
                            elseif any(strcmp(app.MultiCor_table.ColumnName,'Color')) && ~isnumeric((app.MultiCor_table.Data.Color(n,:))) && startsWith(app.MultiCor_table.Data.Color(n,:), "[")
                                c=sscanf(app.MultiCor_table.Data.Color{n,:}, '[%f %f %f %f %f %f %f %f %f %f]');
                            else
                                c= [1 1 1];
                            end

                            s = uistyle('BackgroundColor',c);
                            addStyle(app.MultiCor_table,s,'cell',[n,find(strcmp(app.MultiCor_table.ColumnName, 'MarkerEdgeColor'))])

                        end
                    end

                end

            else
                s = uistyle('BackgroundColor',[1 1 1 ]);
                addStyle(app.MultiCor_table,s,'column',1:size(app.MultiCor_table.Data,2))
            end

        end


        function FileName = CheckFileName(~,FileName,path)

            if  exist(fullfile(path,FileName),'file')>0
                d = dir(path);
                [~, n, ext] = fileparts(FileName);

                [~, nms, cc] = fileparts({d.name});

                FileName = matlab.lang.makeUniqueStrings([nms(ismember(cc,ext)),{n}]);
                FileName = [FileName{end} ext] ;
            end
        end


        function update_fig_limits(app)
            xlim = app.MinPlotX.MinPlotXFigures.hFig(app.active_plot).ax2plot.XLim;
            ylim = app.MinPlotX.MinPlotXFigures.hFig(app.active_plot).ax2plot.YLim;
            app.XLimitsEditField_min.Value = xlim(1);
            app.XLimitsEditField_max.Value = xlim(2);
            app.YLimitsEditField_min.Value = ylim(1);
            app.YLimitsEditField_max.Value = ylim(2);
        end

        function update_fig_prop_fields(app)
            if isempty(app.active_plot) || numel(app.MinPlotX.MinPlotXFigures.hFig_type)<app.active_plot || not(isgraphics(app.MinPlotX.MinPlotXFigures.hFig(app.active_plot).ax2plot))
                UpdateListButtonPushed(app)
                return
            end
            try
                ax2plot = app.MinPlotX.MinPlotXFigures.hFig(app.active_plot).ax2plot;

                app.FigureFontSizeSpinner.Value = ax2plot.FontSize;

                if isempty(app.FontDropDown.Items)
                    app.FontDropDown.Items = listfonts;

                end
                app.FontDropDown.Value = ax2plot.FontName;

                %%
                if not(isempty(ax2plot.UserData))&& isfield(ax2plot.UserData,'LegendMarkerSize')
                    app.LegendMarkerSizeSpinner.Value = ax2plot.UserData.LegendMarkerSize;
                else
                    app.LegendMarkerSizeSpinner.Value = 6;
                end
                %%

                app.LocationDropDown.Value=ax2plot.Legend.Location;
                app.NumberofcolumnsSpinner.Value=ax2plot.Legend.NumColumns;
                app.OrientationDropDown.Value=ax2plot.Legend.Orientation;

                app.XLabelEditField.Value = ax2plot.XLabel.String;
                app.YLabelEditField.Value = ax2plot.YLabel.String;
                app.ZLabelEditField.Value = ax2plot.ZLabel.String;

                app.XLabelInterpreterDropDown.Value = ax2plot.XAxis.Label.Interpreter;
                app.YLabelInterpreterDropDown.Value = ax2plot.YAxis.Label.Interpreter;
                app.ZLabelInterpreterDropDown.Value = ax2plot.ZAxis.Label.Interpreter;

                app.XDirDropDown.Value = ax2plot.XDir;
                app.YDirDropDown.Value = ax2plot.YDir;
                app.ZDirDropDown.Value = ax2plot.ZDir;

                app.XAxesPosDropDown.Value = ax2plot.XAxisLocation;
                app.YAxesPosDropDown.Value = ax2plot.YAxisLocation;

                if isempty(ax2plot.Colorbar)
                    app.ColorbarLabelEditField.Visible = false;
                    app.ColorbarLabelEditField_Label.Visible = false;
                    app.ColorbarLabelInterpreterDropDown.Visible = false;
                    app.ColorbarLabelInterpreterDropDown_Label.Visible = false;
                else
                    app.ColorbarLabelEditField.Visible = true;
                    app.ColorbarLabelEditField_Label.Visible = true;
                    app.ColorbarLabelInterpreterDropDown.Visible = true;
                    app.ColorbarLabelInterpreterDropDown_Label.Visible = true;


                    app.ColorbarLabelEditField.Value = ax2plot.Colorbar.Label.String;
                    app.ColorbarLabelInterpreterDropDown.Value = ax2plot.Colorbar.Label.Interpreter;
                end


                app.XScaleDropDown.Value = ax2plot.XScale;
                app.YScaleDropDown.Value = ax2plot.YScale;
                app.ZScaleDropDown.Value = ax2plot.ZScale;


                app.XGridDropDown.Value = ax2plot.XGrid;
                app.YGridDropDown.Value = ax2plot.YGrid;
                app.ZGridDropDown.Value = ax2plot.ZGrid;

                app.XLimitsEditField_min.Value = app.MinPlotX.MinPlotXFigures.hFig(app.active_plot).ax2plot.XLim(1);
                app.XLimitsEditField_max.Value = app.MinPlotX.MinPlotXFigures.hFig(app.active_plot).ax2plot.XLim(2);
                app.YLimitsEditField_min.Value = app.MinPlotX.MinPlotXFigures.hFig(app.active_plot).ax2plot.YLim(1);
                app.YLimitsEditField_max.Value = app.MinPlotX.MinPlotXFigures.hFig(app.active_plot).ax2plot.YLim(2);
                app.ZLimitsEditField_min.Value = app.MinPlotX.MinPlotXFigures.hFig(app.active_plot).ax2plot.ZLim(1);
                app.ZLimitsEditField_max.Value = app.MinPlotX.MinPlotXFigures.hFig(app.active_plot).ax2plot.ZLim(2);

            catch ME
                DisplayError(ME,'EasyPlot update fig prop fields')
            end

        end

        function edit_fig_props(app,ax2plot)
            if isempty(ax2plot)  || not(isgraphics(ax2plot))
                return
            end
            try
                ax2plot.FontSize = app.FigureFontSizeSpinner.Value;

                ax2plot.FontName = app.FontDropDown.Value;

                legend_update_fun(app)


                ax2plot.XLabel.String = app.XLabelEditField.Value;
                ax2plot.YLabel.String = app.YLabelEditField.Value;
                ax2plot.ZLabel.String = app.ZLabelEditField.Value;

                ax2plot.XAxis.Label.Interpreter = app.XLabelInterpreterDropDown.Value;
                ax2plot.YAxis.Label.Interpreter = app.YLabelInterpreterDropDown.Value;
                ax2plot.ZAxis.Label.Interpreter = app.ZLabelInterpreterDropDown.Value;

                ax2plot.XDir = app.XDirDropDown.Value;
                ax2plot.YDir = app.YDirDropDown.Value;
                ax2plot.ZDir = app.ZDirDropDown.Value;

                ax2plot.XAxisLocation = app.XAxesPosDropDown.Value;
                ax2plot.YAxisLocation = app.YAxesPosDropDown.Value;

                if not(isempty(ax2plot.Colorbar))
                    ax2plot.Colorbar.Label.String = app.ColorbarLabelEditField.Value;
                    ax2plot.Colorbar.Label.Interpreter = app.ColorbarLabelInterpreterDropDown.Value;
                end


                ax2plot.XScale = app.XScaleDropDown.Value;
                ax2plot.YScale = app.YScaleDropDown.Value;
                ax2plot.ZScale = app.ZScaleDropDown.Value;


                ax2plot.XGrid = app.XGridDropDown.Value;
                ax2plot.YGrid = app.YGridDropDown.Value;
                ax2plot.ZGrid = app.ZGridDropDown.Value;

                if app.CustomAxLimitsCheckBox.Value
                    app.MinPlotX.MinPlotXFigures.hFig(app.active_plot).ax2plot.XLim(1) = app.XLimitsEditField_min.Value;
                    app.MinPlotX.MinPlotXFigures.hFig(app.active_plot).ax2plot.XLim(2) = app.XLimitsEditField_max.Value;
                    app.MinPlotX.MinPlotXFigures.hFig(app.active_plot).ax2plot.YLim(1) = app.YLimitsEditField_min.Value;
                    app.MinPlotX.MinPlotXFigures.hFig(app.active_plot).ax2plot.YLim(2) = app.YLimitsEditField_max.Value;
                    app.MinPlotX.MinPlotXFigures.hFig(app.active_plot).ax2plot.ZLim(1) = app.ZLimitsEditField_min.Value;
                    app.MinPlotX.MinPlotXFigures.hFig(app.active_plot).ax2plot.ZLim(2) = app.ZLimitsEditField_max.Value;
                end
                %drawnow
            catch ME
                DisplayError(ME,'EasyPlot edit figure properties')
            end
        end

        function [line_obj,all_lines,tags,tagIdx ] = get_line_objects(~,ax2plot)
            all_lines = flipud(findall(ax2plot, 'Type', 'line'));
            if isempty(all_lines)
                line_obj = [];
                tags = [];
                tagIdx = [];
                return
            end
            % Get unique tags
            tags = get(all_lines, 'Tag');
            if ischar(tags)
                tags = {tags};  % convert single char to cell array
            end

            % Filter out empty tags
            nonEmptyMask = ~cellfun(@isempty, tags);
            all_lines = all_lines(nonEmptyMask);
            tags = tags(nonEmptyMask);

            % Get unique tags and group lines
            [~, ~, tagIdx] = unique((tags), 'stable');
            all_lines = (all_lines);

            % Get first occurrence of each tag
            line_obj = (all_lines(accumarray(tagIdx(:), (1:length(tagIdx))', [], @min)));
            tags = (tags);
        end


        function colData=convert_col2str(app,colData)
            for r = 1:numel(colData)
                x = colData{r};
                if isnumeric(x) && numel(x) > 1
                    colData{r} = mat2str(round(x(1:3),4));
                end
            end
        end
    end

    methods (Access = public)

        function MinPlotXEasyPlot_public_update(app,MinPlotXFigures)
            %             if isempty(app) || ~isvalid(app)
            %                 return
            %             end
            %
            %             if ~isprop(app,'Tree') || isempty(app.Tree) || ~isvalid(app.Tree)
            %                 return
            %             end
            try

                if exist("MinPlotXFigures","var")
                    app.MinPlotX.MinPlotXFigures = MinPlotXFigures;
                end


                if isnumeric(app.MinPlotX) || (~isstruct(app.MinPlotX) && ~isvalid(app.MinPlotX))
                    %figs = findall(0,'Type','figure');
                    update_ScatterObject_table(app)
                    % delete(app)
                    return
                end

                Tree2Update = app.Tree;
                ResetTree(app,Tree2Update);

                try
                    if isfield(app.MinPlotX.MinPlotXFigures,'hFig_type')&& not(isempty(app.MinPlotX.MinPlotXFigures.hFig_type))
                        app.MinPlotX.MinPlotXFigures.hFig_type = app.MinPlotX.MinPlotXFigures.hFig_type(cellfun(@isgraphics,{app.MinPlotX.MinPlotXFigures.hFig.ax2plot}));
                        app.MinPlotX.MinPlotXFigures.hFig_description = app.MinPlotX.MinPlotXFigures.hFig_description(cellfun(@isgraphics,{app.MinPlotX.MinPlotXFigures.hFig.ax2plot}));
                        app.MinPlotX.MinPlotXFigures.hFig = app.MinPlotX.MinPlotXFigures.hFig(cellfun(@isgraphics,{app.MinPlotX.MinPlotXFigures.hFig.ax2plot}));
                    else
                        app.MinPlotX.MinPlotXFigures.hFig_type = [];
                        app.MinPlotX.MinPlotXFigures.hFig_description = [];
                        app.MinPlotX.MinPlotXFigures.hFig = [];
                    end
                catch ME
                    DisplayError(ME,'EasyPlotModule Public Update Error')
                    app.MinPlotX.MinPlotXFigures.hFig_type = [];
                    app.MinPlotX.MinPlotXFigures.hFig_description = [];
                    app.MinPlotX.MinPlotXFigures.hFig = [];

                end


                hFig_type = app.MinPlotX.MinPlotXFigures.hFig_type;
                hFig_description = app.MinPlotX.MinPlotXFigures.hFig_description;

                if isempty(hFig_type)
                    %    delete(app)
                    update_ScatterObject_table(app)
                    %                 app.active_plot=[];
                    return
                end

                for n = 1:numel(hFig_type)
                    uitreenode(Tree2Update,'Text',string(hFig_description{n}),'NodeData',hFig_type{n});
                end


                if isempty(app.active_plot) ||numel(app.active_plot)>1 || numel(hFig_type)<app.active_plot
                    app.active_plot = 1;
                end
                update_ScatterObject_table(app)

                %             if not(isempty(app.mainapp) || ~isvalid(app.mainapp))
                %                 app.mainapp.MinPlotX = app.MinPlotX;
                %             end

                if not(isempty(app.Tree.Children))
                    app.Tree.SelectedNodes = app.Tree.Children(app.active_plot);
                else
                    app.Tree.SelectedNodes = [];
                end
                update_fig_prop_fields(app)
            catch ME

                DisplayError(ME)

            end
        end

        function ExportFigureFun(app,n)

            path = app.ExportDirectoryEditField.Value;

            if not(strcmp(app.Tree.Children(n).NodeData,app.MinPlotX.MinPlotXFigures.hFig_type{n}))
                MinPlotXEasyPlot_public_update(app)
            end

            plot_name = app.MinPlotX.MinPlotXFigures.hFig_type{n};
            if not(isempty(app.CustStrEditField.Value)) && not(endsWith(app.CustStrEditField.Value,'_'))
                FileName = [ app.CustStrEditField.Value '_' plot_name];
            elseif not(isempty(app.CustStrEditField.Value)) && (endsWith(app.CustStrEditField.Value,'_'))
                FileName = [ app.CustStrEditField.Value plot_name];
            else
                FileName = plot_name;

            end
            hFig = app.MinPlotX.MinPlotXFigures.hFig(n).ax2plot(1).Parent(1).Parent(1);


            if app.CheckBox_fig_exp.Value == true
                FileName_ = CheckFileName(app,[FileName '.fig'],path);
                savefig(hFig,fullfile(path,FileName_))
            end
            if app.CheckBox_eps_exp.Value == true
                FileName_ = CheckFileName(app,[FileName '.eps'],path);
                exportgraphics(hFig,fullfile(path,FileName_),'BackgroundColor','none','ContentType','vector')
            end
            if app.CheckBox_png_exp.Value == true
                FileName_ = CheckFileName(app,[FileName '.png'],path);
                exportgraphics(hFig,fullfile(path,FileName_),Resolution = 300)
            end
            if app.CheckBox_tif_exp.Value == true
                FileName_ = CheckFileName(app,[FileName '.tif'],path);
                exportgraphics(hFig,fullfile(path,FileName_),Resolution = 1200)
            end
            if app.CheckBox_jpg_exp.Value == true
                FileName_ = CheckFileName(app,[FileName '.jpg'],path);
                exportgraphics(hFig,fullfile(path,FileName_))
            end

            if app.CheckBox_pdf_exp.Value == true
                FileName_ = CheckFileName(app,[FileName '.pdf'],path);
                exportgraphics(hFig,fullfile(path,FileName_),'ContentType','vector')
            end
        end

        function   legend_update_fun(app)
            try
                ax2plot=app.MinPlotX.MinPlotXFigures.hFig(app.active_plot).ax2plot;
                children = app.MinPlotX.MinPlotXFigures.hFig(app.active_plot).ax2plot.Children;
                scatter_obj = flipud(children(strcmp(get(children,'Type'),'scatter')));

                if not(isempty(scatter_obj))
                    if app.ShowLegendCheckBox.Value == false
                        l=legend(ax2plot,scatter_obj,Interpreter = app.LegendInterpreterDropDown.Value);
                        l.Visible = false;
                    else

                        [l,icons,~,~] = legend(ax2plot,scatter_obj(logical([scatter_obj.Visible]')),Interpreter = app.LegendInterpreterDropDown.Value);

                        text = findobj(icons,'Type','text');
                        [text(:).Interpreter] = deal(app.LegendInterpreterDropDown.Value);

                        icons = findobj(icons,'Type','patch');
                        icons = findobj(icons,'Marker','none','-xor');
                        set(icons,'MarkerSize',app.LegendMarkerSizeSpinner.Value);
                    end

                end

                all_lines = flipud(findall(ax2plot, 'Type', 'line'));
                if not(isempty(all_lines))

                    % Get unique tags
                    tags = get(all_lines, 'Tag');
                    if ischar(tags)
                        tags = {tags};  % convert single char to cell array
                    end


                    % Filter out empty tags
                    nonEmptyMask = ~cellfun(@isempty, tags);
                    all_lines = all_lines(nonEmptyMask);
                    tags = tags(nonEmptyMask);

                    % Get unique tags and group lines
                    [~, ~, tagIdx] = unique(tags, 'stable');

                    % Get first occurrence of each tag
                    representativeLines = all_lines(accumarray(tagIdx(:), (1:length(tagIdx))', [], @min));
                    legend_str = app.MultiCor_table.Data.Name;
                    if not(iscell(legend_str))
                        legend_str = {legend_str};
                    end

                    for i = 1:numel(representativeLines)
                        % Update all Tags with the new LegendString
                        set(all_lines(strcmp(tags, representativeLines(i).Tag)), 'Tag', legend_str{i}, 'DisplayName', legend_str{i});
                    end
                    l = legend(ax2plot,(representativeLines), legend_str,'interpreter','none'); % ATH check if flipud
                    l.Interpreter=app.LegendInterpreterDropDown.Value;

                    %   [l,icons,~,~] = legend(ax2plot,(representativeLines), legend_str,'interpreter','none'); % ATH check if flipud

                    %                  text = findobj(icons,'Type','text');
                    %             [text(:).Interpreter] = deal(app.LegendInterpreterDropDown.Value);
                    %
                    %                 icons = findobj(icons,'Type','Line');
                    %
                    %                 for n = 1:numel(representativeLines)
                    %                     set(icons(n),'LineWidth',representativeLines(n).LineWidth);
                    %                 end

                    if app.ShowLegendCheckBox.Value == false
                        l.Visible = false;
                    end
                end

                ax2plot.Legend.Location=app.LocationDropDown.Value;
                ax2plot.Legend.NumColumns=app.NumberofcolumnsSpinner.Value;
                ax2plot.Legend.Orientation=app.OrientationDropDown.Value;


            catch ME
                DisplayError(ME,'EasyPlotModule - Legend update failed')

            end
        end

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, mainapp2)
            movegui(app.EasyPlotModuleUIFigure, 'center');
            if exist('app','var')
                %    return
            end
            if exist('mainapp2','var')
                app.mainapp = mainapp2;
            else
                app.mainapp = [];
            end

            app.MinPlotX = mainapp;
            UpdateListButtonPushed(app)

            if isdeployed
                app.InspectAllPropertiesButton.Visible = false;
            end
            try
                app.FontDropDown.Items = listfonts;

                if isempty(app.ExportDirectoryEditField.Value)
                    try
                        app.ExportDirectoryEditField.Value = app.MinPlotX.WorkingDirectoryEditField.Value;
                    catch
                        app.ExportDirectoryEditField.Value = pwd;
                    end
                end
            catch
                keyboard
            end

        end

        % Button pushed function: RemoveSelectedButton
        function RemoveSelectedButtonPushed(app, event)

            if isempty(app.MultiCor_table.Selection)
                return
            end

            scatter_Id = unique(app.MultiCor_table.Selection(:,1));
            children = app.MinPlotX.MinPlotXFigures.hFig(app.active_plot).ax2plot.Children;             scatter_obj = flipud(children(strcmp(get(children,'Type'),'scatter')));
            thx = ismember(app.MinPlotX.MinPlotXFigures.hFig(app.active_plot).ax2plot.Children,scatter_obj(scatter_Id));
            delete(app.MinPlotX.MinPlotXFigures.hFig(app.active_plot).ax2plot.Children(thx))


            update_ScatterObject_table(app)

        end

        % Button pushed function: UpdateListButton
        function UpdateListButtonPushed(app, event)
            MinPlotXEasyPlot_public_update(app)
        end

        % Value changed function: AdvancedSettingsCheckBox
        function AdvancedSettingsCheckBoxValueChanged(app, event)
            update_ScatterObject_table(app)

            if app.AdvancedSettingsCheckBox.Value == true
                state = true;
            else
                state = false;
            end

            app.ApplyGlobalButton.Visible = state;
            app.MergeSelectionButton.Visible = state;
            app.ApplyGlobaltoselectionButton.Visible = state;
            app.MoveDownButton.Visible = state;
            app.MoveUpButton.Visible = state;
            app.FlipAllButton.Visible = state;
            app.FlipSelectionButton.Visible = state;

            app.ReadStyleButton.Visible = state;
            app.ExportStyleButton.Visible = state;
            app.ReplaceStringinNameButton.Visible = state;


        end

        % Value changed function: ColorPreviewCheckBox
        function ColorPreviewCheckBoxValueChanged(app, event)
            update_ScatterObject_table(app)

        end

        % Cell edit callback: MultiCor_table
        function MultiCor_tableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;

            % if multiple colums uialert return

            if (~isempty(app.MultiCor_table.Selection)) && numel(unique(app.MultiCor_table.Selection(:,1)))>1
                answer = uiconfirm(app.EasyPlotModuleUIFigure, ...
                    'Do you want to edit all selected rows?', ...
                    'Edit Multiple Rows', ...
                    'Options', {'Yes', 'No', 'Cancel'});

                switch answer
                    case 'Yes'
                        all = true;
                    case 'No'
                        all = false;
                    case 'Cancel'
                        update_ScatterObject_table(app)
                        return;
                end
            end


            if ~isempty(app.MultiCor_table.Selection) && ismember(indices(:,1),app.MultiCor_table.Selection(:,1))
                scatter_Id = app.MultiCor_table.Selection(:,1);%indices(1);
            else
                scatter_Id = indices(:,1);
            end

            children = app.MinPlotX.MinPlotXFigures.hFig(app.active_plot).ax2plot.Children;             scatter_obj = flipud(children(strcmp(get(children,'Type'),'scatter')));
            [line_obj,all_lines,tags,tagIdx ] = get_line_objects(app,app.MinPlotX.MinPlotXFigures.hFig(app.active_plot).ax2plot);
            for s=1:numel(scatter_Id)
                if isempty(line_obj)
                    switch app.MultiCor_table.ColumnName{indices(2)}
                        case {'MarkerFaceColor','MarkerEdgeColor','Color'}
                            if numel(sscanf(newData, '[%f %f %f %f %f %f %f %f %f %f]')')==3 && not(any(sscanf(newData, '[%f %f %f %f %f %f %f %f %f %f]')'>1))&& not(any(sscanf(newData, '[%f %f %f %f %f %f %f %f %f %f]')'<0))
                                scatter_obj(scatter_Id(s)).(char(app.MultiCor_table.ColumnName(indices(2)))) = deal(sscanf(newData, '[%f %f %f %f %f %f %f %f %f %f]')');
                            elseif isnumeric(newData) && numel(newData)==3 && ~any(newData>1) &&~any(newData<0)
                                scatter_obj(scatter_Id(s)).(char(app.MultiCor_table.ColumnName(indices(2)))) = (newData);
                            elseif any(strcmp(newData,{ 'green','blue','cyan','magenta','yellow','black','white', 'none'}))  || (ischar(startsWith(newData,'#')) && numel(newData)==6 && not(isnan(str2double(newData(2:end)))) )
                                scatter_obj(scatter_Id(s)).(char(app.MultiCor_table.ColumnName(indices(2)))) = deal(newData);
                            else
                                message = {'Values must be entered as [1 1 1], other allowed values are green, blue, cyan, magenta, yellow, black, white, and none','Numeric values must be between 0 and 1 or hexadecimal color codes consist of # followed by three or six hexadecimal digits!'};
                                uialert(app.EasyPlotModuleUIFigure,message,'Wrong Input','Icon','Error');
                                update_ScatterObject_table(app)
                                return
                            end

                            scatter_obj(scatter_Id(s)).(char(app.MultiCor_table.ColumnName(indices(2)))) = newData;
                            if numel(scatter_Id)==s
                                update_ScatterObject_table(app)
                            end
                        case 'SymbolSize'
                            if not(isnumeric(newData)) || newData<0
                                message = {'Value must be larger than 0!'};
                                uialert(app.EasyPlotModuleUIFigure,message,'Wrong Input','Icon','Error');
                                update_ScatterObject_table(app)
                                return
                            end
                            scatter_obj(scatter_Id(s)).SizeData = newData;
                        case {'MarkerFaceAlpha','MarkerEdgeAlpha'}
                            if not(isnumeric(newData)) || newData>1 ||newData<0
                                message = {'Value must be between 0 and 1!'};
                                uialert(app.EasyPlotModuleUIFigure,message,'Wrong Input','Icon','Error');
                                update_ScatterObject_table(app)
                                return
                            end
                            scatter_obj(scatter_Id(s)).(char(app.MultiCor_table.ColumnName(indices(2)))) = newData;

                        case 'Symbol'
                            %panelty","^","v",">","<","","hexagram
                            if not(ismember(newData,{'square','diamond','pentagram','hexagram','o', '+', '*', '.', 'x', '_', '|', 's', 'd', '^', 'v', '>', '<', 'p', 'h', 'none'}))
                                message = {'Only the following options are allowed: ','''square'',''diamond'',''pentagram'',''hexagram'',''o'', ''+'', ''*'', ''.'', ''x'', ''_'', ''|'', ''s'', ''d'', ''^'', ''v'', ''>'', ''<'', ''p'', ''h'', ''none'''};
                                uialert(app.EasyPlotModuleUIFigure,message,'Wrong Input','Icon','Error');
                                update_ScatterObject_table(app)
                                return
                            end
                            scatter_obj(scatter_Id(s)).Marker = newData;
                        case 'Active'
                            %panelty
                            scatter_obj(scatter_Id(s)).Visible = newData;
                        case 'Name'
                            scatter_obj(scatter_Id(s)).DisplayName = newData;
                        otherwise
                            try
                                scatter_obj(scatter_Id(s)).(char(app.MultiCor_table.ColumnName(indices(2)))) = newData;
                            catch ME
                                DisplayError(ME)
                            end
                    end
                elseif isempty(scatter_obj)

                    switch app.MultiCor_table.ColumnName{indices(2)}
                        case {'MarkerFaceColor','MarkerEdgeColor','Color'}
                            if numel(sscanf(newData, '[%f %f %f %f %f %f %f %f %f %f]')')==3 && not(any(sscanf(newData, '[%f %f %f %f %f %f %f %f %f %f]')'>1))&& not(any(sscanf(newData, '[%f %f %f %f %f %f %f %f %f %f]')'<0))
                                [all_lines(tagIdx == scatter_Id(s)).(char(app.MultiCor_table.ColumnName(indices(2))))] = deal(sscanf(newData, '[%f %f %f %f %f %f %f %f %f %f]')');
                            elseif isnumeric(newData) && numel(newData)==3 && ~any(newData>1) &&~any(newData<0)
                                [all_lines(tagIdx == scatter_Id(s)).(char(app.MultiCor_table.ColumnName(indices(2))))] = deal(newData);
                            elseif strcmp(newData,'auto')||strcmp(newData,'none')
                                [all_lines(tagIdx == scatter_Id(s)).(char(app.MultiCor_table.ColumnName(indices(2))))] = deal(newData);

                            else
                                message = {'Values must be entered as [1 1 1], none, or auto','Numeric values must be between 0 and 1!'};
                                uialert(app.EasyPlotModuleUIFigure,message,'Wrong Input','Icon','Error');
                                update_ScatterObject_table(app)
                                return
                            end
                            if numel(scatter_Id)==s
                                update_ScatterObject_table(app)
                            end
                        case 'SymbolSize'
                            if not(isnumeric(newData)) || newData<0
                                message = {'Value must be larger than 0!'};
                                uialert(app.EasyPlotModuleUIFigure,message,'Wrong Input','Icon','Error');
                                update_ScatterObject_table(app)
                                return
                            end
                            [all_lines(tagIdx == scatter_Id(s)).MarkerSize] = deal(newData);
                        case 'LineWidth'
                            if not(isnumeric(newData)) || newData<0
                                message = {'Value must be larger than 0!'};
                                uialert(app.EasyPlotModuleUIFigure,message,'Wrong Input','Icon','Error');
                                update_ScatterObject_table(app)
                                return
                            end
                            [all_lines(tagIdx == scatter_Id(s)).LineWidth] = deal(newData);
                        case {'LineAlpha'}
                            if not(isnumeric(newData)) || newData>1 ||newData<0
                                message = {'Value must be between 0 and 1!'};
                                uialert(app.EasyPlotModuleUIFigure,message,'Wrong Input','Icon','Error');
                                update_ScatterObject_table(app)
                                return
                            end

                            %                             %                        [all_lines(tagIdx == scatter_Id(s)).(char(app.MultiCor_table.ColumnName(indices(2))))] = deal(newData);
                            %
                            thx = cell2mat({all_lines(tagIdx == scatter_Id(s)).Color}');
                            thx = thx(1,:);
                            thx(1,4) = newData;

                            [all_lines(tagIdx == scatter_Id(s)).Color] = deal(thx);
                            UserData.LineAlpha=newData;
                            [all_lines(tagIdx == scatter_Id(s)).UserData] = deal(UserData);

                        case 'Symbol'
                            % if not(ismember(newData,{'-' , '--' , ':' , '-.' , 'none','A-' , 'A--' , 'A:' , 'A-.' , 'Anone',}))
                            %                             message = {'Only the following options are allowed:','''-'', ''--'', '':'', ''-.'', ''none'' ,''A-'' ,''A--'', ''A:'', ''A-.'', ''Anone'''};

                            if not(ismember(newData,{'none','-','--',':','-.','o','+','*','.','x','_','|','s','d','^','v','>','<','p','h','square','diamond','pentagram','hexagram',...
                                    '-o','-+','-*','-.','-x','-_','-|','-s','-d','-^','-v','->','-<','-p','-h','-square','-diamond','-pentagram','-hexagram',...
                                    '--o','--+','--*','--.','--x','--_','--|','--s','--d','--^','--v','-->','--<','--p','--h','--square','--diamond','--pentagram','--hexagram',...
                                    ':o',':+',':*','::.','::x',':_','::|',':s',':d',':^',':v',':>','::<',':p',':h',':square',':diamond',':pentagram',':hexagram',...
                                    '-.o','-.+','-.*','-..','-.x','-._','-.|','-.s','-.d','-.^','-.v','-.>','-.<','-.p','-.h','-.square','-.diamond','-.pentagram','-.hexagram'}))

                                message = {'Only the following options are allowed: ','''-'',''--'','':'',''-.'',''-o'',''square'',''diamond'',''pentagram'',''hexagram'',''o'', ''+'', ''*'', ''.'', ''x'', ''_'', ''|'', ''s'', ''d'', ''^'', ''v'', ''>'', ''<'', ''p'', ''h'', ''none'''};
                                uialert(app.EasyPlotModuleUIFigure,message,'Wrong Input','Icon','Error');
                                update_ScatterObject_table(app)
                                return
                            end

                            [all_lines(tagIdx == scatter_Id(s)).Marker] = deal(newData);

                        case 'Active'
                            [all_lines(tagIdx == scatter_Id(s)).Visible] = deal(newData);

                        case 'Name'

                            [all_lines(tagIdx == scatter_Id(s)).DisplayName] = deal(newData);

                        otherwise
                            [all_lines(tagIdx == scatter_Id(s)).(char(app.MultiCor_table.ColumnName(indices(2))))] = deal(newData);
                    end
                end

                legend_update_fun(app)
            end
            if numel(scatter_Id)>1
                update_ScatterObject_table(app)
            end

        end

        % Button down function: MultiCor_table
        function MultiCor_tableButtonDown(app, event)
            click_threshold_s = 0.5;
            if isempty(app.click_time)
                app.click_time = tic();
            else

                time_between_clicks = toc(app.click_time );
                app.click_time = tic();
                if time_between_clicks < click_threshold_s
                    % if field == Color
                    if isempty(app.MultiCor_table.Selection)|| not(any(contains(app.MultiCor_table.ColumnName(app.MultiCor_table.Selection(:,2)), 'Color')))
                        return
                    end
                    if any(ismember(unique(app.MultiCor_table.Selection(:,2)),5)) && endsWith(app.MinPlotX.MinPlotXFigures.hFig(app.active_plot).options.type.Value,'XYZ')
                        selection = uiconfirm(app.EasyPlotModuleUIFigure,'Do you want to change the XYZ Plot MarkerFaceColor','XYZ options', ...
                            'Options',{'Use Single Color','Use Colormap','Cancel'}, ...
                            'DefaultOption',2,'CancelOption',3);
                        if strcmp(selection,'Cancel')
                            return
                        elseif strcmp(selection,'Use Single Color')
                            c = uisetcolor;  % Enter desired callback / action here.
                            figure(app.EasyPlotModuleUIFigure)
                            if numel(c) == 1 && c == 0
                                return
                            end
                        elseif strcmp(selection,'Use Colormap')
                            c = 'flat';

                        end

                    else
                        c = uisetcolor;  % Enter desired callback / action here.
                        figure(app.EasyPlotModuleUIFigure)
                        if numel(c) == 1 && c == 0
                            return
                        end
                    end


                    scatter_Id = unique(app.MultiCor_table.Selection(:,1));
                    children = app.MinPlotX.MinPlotXFigures.hFig(app.active_plot).ax2plot.Children;             scatter_obj = flipud(children(strcmp(get(children,'Type'),'scatter')));
                    [line_obj,all_lines,tags,tagIdx ] = get_line_objects(app,app.MinPlotX.MinPlotXFigures.hFig(app.active_plot).ax2plot);
                    if isempty(line_obj)
                        if any(contains(app.MultiCor_table.ColumnName(app.MultiCor_table.Selection(:,2)), 'MarkerFaceColor'))
                            [scatter_obj(scatter_Id).MarkerFaceColor] = deal(c);
                        elseif any(contains(app.MultiCor_table.ColumnName(app.MultiCor_table.Selection(:,2)), 'MarkerEdgeColor'))
                            [scatter_obj(scatter_Id).MarkerEdgeColor] = deal(c);
                        end

                    elseif isempty(scatter_obj)

                        for nn = 1:numel(scatter_Id)
                            if any(strcmp(app.MultiCor_table.ColumnName(app.MultiCor_table.Selection(:,2)),'MarkerFaceColor'))
                                [all_lines(tagIdx == scatter_Id(nn)).MarkerFaceColor] = deal(c);
                            elseif any(strcmp(app.MultiCor_table.ColumnName(app.MultiCor_table.Selection(:,2)),'MarkerEdgeColor'))
                                [all_lines(tagIdx == scatter_Id(nn)).MarkerEdgeColor] = deal(c);
                            elseif any(strcmp(app.MultiCor_table.ColumnName(app.MultiCor_table.Selection(:,2)),'Color'))
                                [all_lines(tagIdx == scatter_Id(nn)).Color] = deal(c);
                            end
                        end
                    end
                    update_ScatterObject_table(app)


                    %  end
                end % if double-click
            end % if empty

        end

        % Selection changed function: Tree
        function TreeSelectionChanged(app, event)

            if isempty(app.active_plot) || numel(app.MinPlotX.MinPlotXFigures.hFig_type)<app.active_plot || not(isgraphics(app.MinPlotX.MinPlotXFigures.hFig(app.active_plot).ax2plot))
                UpdateListButtonPushed(app)
                return
            end

            %  update_ScatterObject_table(app)
            try
                app.active_plot = find(strcmp(app.Tree.SelectedNodes.NodeData,{app.Tree.Children.NodeData}));
            catch
                keyboard
            end


            update_ScatterObject_table(app)
            update_fig_prop_fields(app)


            if ~isempty(app.MinPlotX.MinPlotXFigures.hFig(app.active_plot).ax2plot.Legend)
                app.LegendInterpreterDropDown.Value = app.MinPlotX.MinPlotXFigures.hFig(app.active_plot).ax2plot.Legend.Interpreter;
                app.ShowLegendCheckBox.Value = app.MinPlotX.MinPlotXFigures.hFig(app.active_plot).ax2plot.Legend.Visible;

            else
                app.LegendInterpreterDropDown.Value = 'none';
                app.ShowLegendCheckBox.Value = false;
            end

            xlim = app.MinPlotX.MinPlotXFigures.hFig(app.active_plot).ax2plot.XLim;
            ylim = app.MinPlotX.MinPlotXFigures.hFig(app.active_plot).ax2plot.YLim;
            app.XLimitsEditField_min.Value = xlim(1);
            app.XLimitsEditField_max.Value = xlim(2);
            app.YLimitsEditField_min.Value = ylim(1);
            app.YLimitsEditField_max.Value = ylim(2);


        end

        % Value changed function: LegendInterpreterDropDown, 
        % LocationDropDown, NumberofcolumnsSpinner, OrientationDropDown
        function LegendInterpreterDropDownValueChanged(app, event)
            legend_update_fun(app)
        end

        % Value changed function: ShowLegendCheckBox, ShowLegendCheckBox_2
        function ShowLegendCheckBoxValueChanged(app, event)
            app.ShowLegendCheckBox.Value=event.Value;
            app.ShowLegendCheckBox_2.Value=event.Value;

            legend_update_fun(app)

        end

        % Button pushed function: FindPlotButton
        function FindPlotButtonPushed(app, event)
            try
                CenterFig_fun(app.MinPlotX.MinPlotXFigures.hFig(app.active_plot).ax2plot(1).Parent(1).Parent(1))
            catch
                MinPlotXEasyPlot_public_update(app)
            end
        end

        % Button pushed function: SelectDirButton
        function SelectDirButtonPushed(app, event)
            f = figure('Renderer', 'painters', 'Position', [-100 -100 0 0]); %create a dummy figure so that uigetfile doesn't minimize our GUI
            path = uigetdir(pwd,'select working directory with read/write permission');
            delete(f)

            if ~isequal(path,0)
                app.ExportDirectoryEditField.Value = path;
            end
            figure(app.EasyPlotModuleUIFigure)
        end

        % Button pushed function: ExportSelectedButton
        function ExportSelectedButtonPushed(app, event)
            if not(any(ismember(fieldnames(app.MinPlotX),'MinPlotXFigures'))) || isempty(app.MinPlotX.MinPlotXFigures) ||not(isfield(app.MinPlotX.MinPlotXFigures,'hFig_type')) || isempty(app.MinPlotX.MinPlotXFigures.hFig_type)
                return
            end
            d = uiprogressdlg(app.EasyPlotModuleUIFigure,'Title','Export...',...
                'Message','Exporting Plot','Cancelable','off');

            ExportFigureFun(app,app.active_plot)
            delete(d)
        end

        % Button pushed function: ExportAllButton
        function ExportAllButtonPushed(app, event)
            MinPlotXEasyPlot_public_update(app)
            d = uiprogressdlg(app.EasyPlotModuleUIFigure,'Title','Export...',...
                'Message','Export Started','Cancelable','on');

            for n = 1:numel(app.MinPlotX.MinPlotXFigures.hFig_type)
                d.Value = n/numel(app.MinPlotX.MinPlotXFigures.hFig_type);
                d.Message = sprintf(['Exporting Plot: ' num2str(n) ' of ' num2str(numel(app.MinPlotX.MinPlotXFigures.hFig_type)) ': '   app.MinPlotX.MinPlotXFigures.hFig_type{n} ]);
                ExportFigureFun(app,n)
            end
        end

        % Button pushed function: ExportSelectionButton
        function ExportSelectionButtonPushed(app, event)
            if ~isvalid(app.MinPlotX)
                delete(app)
            end
            MinPlotXEasyPlot_public_update(app)
            if not(any(ismember(fieldnames(app.MinPlotX),'MinPlotXFigures'))) || isempty(app.MinPlotX.MinPlotXFigures) ||not(isfield(app.MinPlotX.MinPlotXFigures,'hFig_type')) || isempty(app.MinPlotX.MinPlotXFigures.hFig_type)
                return
            end

            [indx,~] = listdlg('PromptString',{'Select Groups'},...
                'SelectionMode','multiple','ListString',app.MinPlotX.MinPlotXFigures.hFig_type,'InitialValue',...
                app.active_plot);
            figure(app.EasyPlotModuleUIFigure);

            d = uiprogressdlg(app.EasyPlotModuleUIFigure,'Title','Export...',...
                'Message','Export Started','Cancelable','on');

            for n = 1:numel(indx)
                d.Value = n/numel(app.MinPlotX.MinPlotXFigures.hFig_type);
                d.Message = sprintf(['Exporting Plot: ' num2str(n) ' of ' num2str(numel(indx)) ': '   app.MinPlotX.MinPlotXFigures.hFig_type{indx(n)} ]);
                ExportFigureFun(app,indx(n))
            end
        end

        % Value changed function: CustomAxLimitsCheckBox
        function CustomAxLimitsCheckBoxValueChanged(app, event)
            value = app.CustomAxLimitsCheckBox.Value;

            if value == true
                state = true;
            else
                state = false;
                app.MinPlotX.MinPlotXFigures.hFig(app.active_plot).ax2plot.XLimMode = 'auto';
                app.MinPlotX.MinPlotXFigures.hFig(app.active_plot).ax2plot.YLimMode = 'auto';
                app.MinPlotX.MinPlotXFigures.hFig(app.active_plot).ax2plot.ZLimMode = 'auto';
            end

            app.XLimitsEditField_min.Visible = state;
            app.XLimitsEditField_max.Visible = state;
            app.YLimitsEditField_min.Visible = state;
            app.YLimitsEditField_max.Visible = state;
            app.ZLimitsEditField_min.Visible = state;
            app.ZLimitsEditField_max.Visible = state;
            app.XLimitsEditFieldLabel.Visible = state;
            app.YLimitsEditFieldLabel.Visible = state;
            app.ZLimitsEditFieldLabel.Visible = state;

            %       update_fig_limits(app)

        end

        % Callback function
        function XLimitsEditField_minValueChanged(app, event)
            try
                xlim_val(1) = app.XLimitsEditField_min.Value;
                xlim_val(2) = app.XLimitsEditField_max.Value;
                ylim_val(1) = app.YLimitsEditField_min.Value;
                ylim_val(2) = app.YLimitsEditField_max.Value;

                app.MinPlotX.MinPlotXFigures.hFig(app.active_plot).ax2plot.XLim = sort(xlim_val);
                app.MinPlotX.MinPlotXFigures.hFig(app.active_plot).ax2plot.YLim = sort(ylim_val);
            catch ME
                DisplayError(ME,'XLimitsEditField Update Error')
                CustomAxLimitsCheckBoxValueChanged(app)
            end

        end

        % Button pushed function: ApplyGlobalButton
        function ApplyGlobalMenuSelected(app, event)
            % ATH add if no line is selected use all
            % ATH optional add ask if multiple entries should be updated

            properties_lines = {'Legend'; 'Visible';'Color'; 'LineStyle'; 'LineWidth'; 'UserData'; 'Marker'; ...
                'MarkerEdgeColor'; 'MarkerFaceColor'; 'MarkerSize'};
            properties_scatter = {'Legend'; 'Visible'; 'Marker'; 'SizeData'; ...
                'MarkerFaceColor'; 'MarkerFaceAlpha'; 'MarkerEdgeColor'; ...
                'MarkerEdgeAlpha'; 'LineWidth'};


            object_Id_org = unique(app.MultiCor_table.Selection(:,1));

            if numel(object_Id_org)>2 && numel(app.MultiCor_table.Data.Name(object_Id_org))>numel(unique(app.MultiCor_table.Data.Name(object_Id_org)))
                message = {'Select objects with unique names!'};
                uialert(app.EasyPlotModuleUIFigure,message,'Wrong Input','Icon','Error');
                return
            end
            d = uiprogressdlg(app.EasyPlotModuleUIFigure,'Title','Applying Changes...',...
                'Message','Progress Started','Cancelable','on');

            %% scatter
            children = app.MinPlotX.MinPlotXFigures.hFig(app.active_plot).ax2plot.Children;             scatter_obj_org = flipud(children(strcmp(get(children,'Type'),'scatter')));
            if not(isempty(scatter_obj_org))
                for n_SID = 1:numel(object_Id_org)

                    Name = app.MultiCor_table.Data.Name(object_Id_org(n_SID));


                    w2c = properties_scatter;

                    for n = 1:numel(app.MinPlotX.MinPlotXFigures.hFig)
                        d.Value = n/numel(app.MinPlotX.MinPlotXFigures.hFig);
                        d.Message = sprintf(['Changing appearance in figure: ' num2str(n) ' of ' num2str(numel(app.MinPlotX.MinPlotXFigures.hFig))]);
                        if d.CancelRequested
                            break
                        end

                        try

                            if not(app.active_plot == n)
                                children = app.MinPlotX.MinPlotXFigures.hFig(n).ax2plot.Children;
                                scatter_obj = flipud(children(strcmp(get(children,'Type'),'scatter')));
                                if isempty(scatter_obj)
                                    continue
                                end
                                if any(strcmp({scatter_obj.DisplayName},Name))
                                    scatter_Id = find(strcmp({scatter_obj.DisplayName},Name));

                                    for l = 1:numel(scatter_Id)
                                        for k = 1:numel(w2c)
                                            if strcmp(w2c(k),'Legend')
                                                if l==1
                                                    scatter_obj(scatter_Id(l)).Parent.Legend.Interpreter = scatter_obj_org(object_Id_org(n_SID)).Parent.Legend.Interpreter;
                                                    scatter_obj(scatter_Id(l)).Parent.Legend.Visible = scatter_obj_org(object_Id_org(n_SID)).Parent.Legend.Visible;
                                                end
                                            else
                                                scatter_obj(scatter_Id(l)).(char(w2c(k))) = scatter_obj_org(object_Id_org(n_SID)).(char(w2c(k)));
                                            end
                                        end
                                    end
                                end
                            end
                        catch ME
                            DisplayError(ME,'EasyPlotModule - ApplyGlobal failed')
                            if not(isdeployed)
                                keyboard
                            end
                        end
                    end
                end
            end

            %% lines

            [all_lines_org] = get_line_objects(app,app.MinPlotX.MinPlotXFigures.hFig(app.active_plot).ax2plot);

            if not(isempty(all_lines_org))
                for n_SID = 1:numel(object_Id_org)

                    Name = app.MultiCor_table.Data.Name(object_Id_org(n_SID));


                    w2c = properties_lines;

                    for n = 1:numel(app.MinPlotX.MinPlotXFigures.hFig)
                        d.Value = n/numel(app.MinPlotX.MinPlotXFigures.hFig);
                        d.Message = sprintf(['Changing appearance in figure: ' num2str(n) ' of ' num2str(numel(app.MinPlotX.MinPlotXFigures.hFig))]);
                        if d.CancelRequested
                            break
                        end

                        try

                            if not(app.active_plot == n)
                                [lines_obj,all_lines] = get_line_objects(app,app.MinPlotX.MinPlotXFigures.hFig(n).ax2plot);
                                if isempty(all_lines)
                                    continue
                                end
                                if any(strcmp({all_lines.DisplayName},Name))
                                    Lines_Id = find(strcmp({all_lines.DisplayName},Name));

                                    for l = 1:numel(Lines_Id)
                                        for k = 1:numel(w2c)
                                            if strcmp(w2c(k),'Legend')
                                                if l==1
                                                    all_lines(Lines_Id(l)).Parent.Legend.Interpreter = all_lines_org(object_Id_org(n_SID)).Parent.Legend.Interpreter;
                                                    all_lines(Lines_Id(l)).Parent.Legend.Visible = all_lines_org(object_Id_org(n_SID)).Parent.Legend.Visible;
                                                end
                                            elseif strcmp(w2c(k),'LineAlpha')
                                                if isfield(all_lines_org(object_Id_org(n_SID)).UserData,'LineAlpha')
                                                    alpha=all_lines_org(object_Id_org(n_SID)).UserData.LineAlpha;
                                                else
                                                    alpha=1;
                                                end
                                                all_lines(Lines_Id(l)).Color(4) = alpha;
                                                all_lines(Lines_Id(l)).UserData.LineAlpha=alpha;

                                            else
                                                all_lines(Lines_Id(l)).(char(w2c(k))) = all_lines_org(object_Id_org(n_SID)).(char(w2c(k)));
                                            end
                                        end
                                    end
                                end
                            end
                        catch ME
                            DisplayError(ME,'EasyPlotModule - ApplyGlobal failed')
                            if not(isdeployed)
                                keyboard
                            end
                        end
                    end
                end
            end
        end

        % Button pushed function: ApplyGlobaltoselectionButton
        function ApplyGlobaltoselectionMenuSelected(app, event)
            scatter_Id_org = unique(app.MultiCor_table.Selection(:,1));
            if numel(scatter_Id_org) == 1
                children = app.MinPlotX.MinPlotXFigures.hFig(app.active_plot).ax2plot.Children;             scatter_obj_org = flipud(children(strcmp(get(children,'Type'),'scatter')));
                d = uiprogressdlg(app.EasyPlotModuleUIFigure,'Title','Generating List Of Items',...
                    'Message','Progress Started','Cancelable','on');

                for n = 1:numel(app.MinPlotX.MinPlotXFigures.hFig)
                    d.Value = n/numel(app.MinPlotX.MinPlotXFigures.hFig);
                    if d.CancelRequested
                        break
                    end


                    try
                        children = app.MinPlotX.MinPlotXFigures.hFig(n).ax2plot.Children;                                             scatter_obj = flipud(children(strcmp(get(children,'Type'),'scatter')));
                        if not(isempty(scatter_obj))
                            if not(exist('Name','var'))
                                Name = {scatter_obj.DisplayName};
                            else

                                Name = unique([{scatter_obj.DisplayName},Name]);
                                d.Message = sprintf([num2str(numel(Name)) ' Objects detected']);
                            end
                        end
                    catch ME
                        DisplayError(ME,'Apply Global to selection Error 01')
                        if not(isdeployed)
                            keyboard
                        end
                    end
                end
            end

            if not(exist('Name','var'))
                return
            else
                list2 = Name;
                [indx,~] = listdlg('ListString',list2,'SelectionMode','multi');
                figure(app.EasyPlotModuleUIFigure);
                Name = list2(indx);
                if isempty(Name)
                    return
                end
            end

            answer = uiconfirm(app.EasyPlotModuleUIFigure, ...
                ['Do you want to update the name for all selected objects?'],"Update Name", ...
                "Icon","warning","Options",["Yes","No","Cancel"]);

            if strcmp(answer,'Cancel')
                return
            elseif strcmp(answer,'Yes')
                w2c = {'DisplayName';'Visible'; 'Marker'; 'SizeData';'MarkerFaceColor';'MarkerFaceAlpha';'MarkerEdgeColor';'MarkerEdgeAlpha';'LineWidth';'UserData';'Legend'};
            elseif strcmp(answer,'No')
                w2c = {'Visible'; 'Marker'; 'SizeData';'MarkerFaceColor';'MarkerFaceAlpha';'MarkerEdgeColor';'MarkerEdgeAlpha';'LineWidth';'LineAlpha';'Legend'};

            end

            for n_SID = 1:numel(Name)

                d = uiprogressdlg(app.EasyPlotModuleUIFigure,'Title','Applying Changes...',...
                    'Message','Progress Started','Cancelable','on');

                for n = 1:numel(app.MinPlotX.MinPlotXFigures.hFig)
                    d.Value = n/numel(app.MinPlotX.MinPlotXFigures.hFig);
                    d.Message = sprintf(['Changing appearance in figure: ' num2str(n) ' of ' num2str(numel(app.MinPlotX.MinPlotXFigures.hFig))]);
                    if d.CancelRequested
                        break
                    end

                    try

                        if app.active_plot == n

                        else
                            children = app.MinPlotX.MinPlotXFigures.hFig(n).ax2plot.Children;                                             scatter_obj = flipud(children(strcmp(get(children,'Type'),'scatter')));


                            if not(isempty(scatter_obj))&& any(strcmp({scatter_obj.DisplayName},Name{n_SID}))
                                scatter_Id = find(strcmp({scatter_obj.DisplayName},Name{n_SID}));

                                for l = 1:numel(scatter_Id)
                                    for k = 1:numel(w2c)
                                        if strcmp(w2c(k),'Legend')
                                            if k==1
                                                scatter_obj(scatter_Id(l)).Parent.Legend.Interpreter = scatter_obj_org(scatter_Id_org).Parent.Legend.Interpreter;
                                                scatter_obj(scatter_Id(l)).Parent.Legend.Visible = scatter_obj_org(scatter_Id_org).Parent.Legend.Visible;
                                            end
                                        else
                                            scatter_obj(scatter_Id(l)).(char(w2c(k))) = scatter_obj_org(scatter_Id_org).(char(w2c(k)));
                                        end
                                    end
                                end
                            end
                        end
                    catch ME
                        DisplayError(ME,'Apply Global to selection Error 02')
                        if not(isdeployed)
                            keyboard
                        end
                    end

                end
            end

        end

        % Button pushed function: CloseallfiguresButton
        function CloseallfiguresButtonPushed(app, event)
            if ~isnumeric(app.MinPlotX) && (isstruct(app.MinPlotX) || isvalid(app.MinPlotX))
                for n = 1:numel(app.MinPlotX.MinPlotXFigures.hFig)
                    try
                        delete(app.MinPlotX.MinPlotXFigures.hFig(n).ax2plot(1).Parent(1).Parent(1))
                    catch ME
                        DisplayError(ME,'Error deleting figure')
                    end
                end

            end
            MinPlotXEasyPlot_public_update(app)
            delete(app)
        end

        % Button pushed function: MergeSelectionButton
        function MergeSelectionButtonPushed(app, event)
            scatter_Id_org = unique(app.MultiCor_table.Selection(:,1));
            [lines_obj,all_lines] = get_line_objects(app,app.MinPlotX.MinPlotXFigures.hFig(app.active_plot).ax2plot);

            if numel(scatter_Id_org)>2 && numel(app.MultiCor_table.Data.Name(scatter_Id_org))>numel(unique(app.MultiCor_table.Data.Name(scatter_Id_org)))
                message = {'Select objects with unique names!'};
                uialert(app.EasyPlotModuleUIFigure,message,'Wrong Input','Icon','Error');
                return
            end

            children = app.MinPlotX.MinPlotXFigures.hFig(app.active_plot).ax2plot.Children;             scatter_obj_org = flipud(children(strcmp(get(children,'Type'),'scatter')));
            if not(isempty(scatter_obj_org))
                obj_org = scatter_obj_org;
                if numel(unique({obj_org(scatter_Id_org).Marker})) == 1 && numel(unique(cell2mat({obj_org(scatter_Id_org).SizeData}'),'rows')) == 1&&...
                        numel(unique(cell2mat({obj_org(scatter_Id_org).MarkerFaceColor}'),'rows')) == 3 && numel(unique(cell2mat({obj_org(scatter_Id_org).MarkerFaceAlpha}'),'rows')) == 1 &&...
                        numel(unique(cell2mat({obj_org(scatter_Id_org).MarkerEdgeColor}'),'rows')) == 3 && numel(unique(cell2mat({obj_org(scatter_Id_org).MarkerEdgeAlpha}'),'rows')) == 1 &&...
                        numel(unique(cell2mat({obj_org(scatter_Id_org).LineWidth}'),'rows')) == 1

                    target_obj = scatter_Id_org(1);
                else
                    list2 = app.MultiCor_table.Data.Name(scatter_Id_org);
                    [indx,~] = listdlg('PromptString',{'Which entry should be the target?'},'ListString',list2,'SelectionMode','multi');

                    target_obj = scatter_Id_org(indx);
                end
                for n_SID = 1:numel(scatter_Id_org)
                    if not(scatter_Id_org(n_SID) == target_obj)

                        obj_org(target_obj).XData = [obj_org(target_obj).XData obj_org(scatter_Id_org(n_SID)).XData];
                        obj_org(target_obj).YData = [obj_org(target_obj).YData obj_org(scatter_Id_org(n_SID)).YData];
                        obj_org(target_obj).ZData = [obj_org(target_obj).ZData obj_org(scatter_Id_org(n_SID)).ZData];
                        %% add x and y values to selected.
                        %% remove empty ones

                    end
                end
                delete(obj_org(scatter_Id_org(not(scatter_Id_org == target_obj))))

            elseif not(isempty(all_lines))
                state_ini=app.AdvancedSettingsCheckBox.Value;
                state_color=app.ColorPreviewCheckBox.Value;
                app.AdvancedSettingsCheckBox.Value=true;
                app.ColorPreviewCheckBox.Value=false;
                update_ScatterObject_table(app)

                app.AdvancedSettingsCheckBox.Value=state_ini;
                T=app.MultiCor_table.Data(scatter_Id_org,3:end);
                uniqueCounts = zeros(1, width(T)); % preallocate

                for col = 1:width(T)
                    colData = T{:, col};
                    if ~iscell(colData)
                        colData = num2cell(colData);
                    end
                    strData = cellfun(@(x) mat2str(x), colData, 'UniformOutput', false);

                    uniqueCounts(col) = numel(unique(strData));
                end

                if not(any(uniqueCounts>1))
                    target_obj = scatter_Id_org(1);
                    obj_to_merge = scatter_Id_org(2:end);
                else
                    list2 = app.MultiCor_table.Data.Name(scatter_Id_org);
                    [indx,~] = listdlg('PromptString',{'Which entry should be the target?'},'ListString',list2,'SelectionMode','multi');


                    target_obj = scatter_Id_org(indx);
                    obj_to_merge = scatter_Id_org(not(ismember(1:numel(list2),indx)));
                end

                properties2update={'Color', 'ColorMode', 'DisplayName', 'LineJoin', 'LineStyle', 'LineStyleMode', 'LineWidth','UserData' , 'Marker', 'MarkerEdgeColor', 'MarkerFaceColor', 'MarkerMode', 'MarkerSize', 'Tag', 'Visible'};

                obj_to_update=find(not(ismember({all_lines.DisplayName},lines_obj(target_obj).DisplayName)) & ismember({all_lines.DisplayName},{lines_obj(obj_to_merge).DisplayName}));

                for n_SID = 1:numel(obj_to_update)
                    for k=1:numel(properties2update)
                        try
                            all_lines(obj_to_update(n_SID)).(char(properties2update(k)))=lines_obj(target_obj).(char(properties2update(k)));
                        catch
                            keyboard
                        end
                    end

                end


                update_ScatterObject_table(app)


                app.ColorPreviewCheckBox.Value=state_color;

            else
                return
            end


            update_ScatterObject_table(app)
        end

        % Button pushed function: MoveUpButton
        function MoveUpButtonPushed(app, event)
            scatter_Id_org = unique(app.MultiCor_table.Selection(:,1));
            app.MultiCor_table.Selection(:,1) = sort(app.MultiCor_table.Selection(:,1));

            children = app.MinPlotX.MinPlotXFigures.hFig(app.active_plot).ax2plot.Children;             scatter_obj_org = flipud(children(strcmp(get(children,'Type'),'scatter')));
            children = app.MinPlotX.MinPlotXFigures.hFig(app.active_plot).ax2plot.Children;             scatter_obj = flipud(children(strcmp(get(children,'Type'),'scatter')));
            [~,all_lines] = get_line_objects(app,app.MinPlotX.MinPlotXFigures.hFig(app.active_plot).ax2plot);
            line_obj_org=all_lines;
            if not(isempty(scatter_obj))
                obj_nr_org = 1:numel(scatter_obj_org);
                max_val = 1;

                for n = 1:numel(app.MultiCor_table.Selection(:,1))
                    if app.MultiCor_table.Selection(n,1)>max_val

                        befor = obj_nr_org(1:(scatter_Id_org(n)-1));
                        after = obj_nr_org(scatter_Id_org(n)+1:size(app.MultiCor_table.Data,1));

                        obj_nr_org = [befor(1:end-1)  obj_nr_org(scatter_Id_org(n)) befor(end) after];
                        app.MultiCor_table.Selection(n,1) = app.MultiCor_table.Selection(n,1)-1;

                    else
                        max_val = max_val+1;
                    end
                end

                scatter_obj_org = scatter_obj_org(obj_nr_org);
                app.MinPlotX.MinPlotXFigures.hFig(app.active_plot).ax2plot.Children(ismember(app.MinPlotX.MinPlotXFigures.hFig(app.active_plot).ax2plot.Children,scatter_obj_org)) = flipud(scatter_obj_org);
                update_ScatterObject_table(app)
            end

            if not(isempty(all_lines))
                [line_obj,all_lines] = get_line_objects(app,app.MinPlotX.MinPlotXFigures.hFig(app.active_plot).ax2plot);
                if not(isempty(line_obj))
                    obj_nr_org = 1:numel(all_lines);

                    max_val = 1;

                    for n = 1:numel(app.MultiCor_table.Selection(:,1))
                        if app.MultiCor_table.Selection(n,1)>max_val

                            befor = obj_nr_org(1:(scatter_Id_org(n)-1));
                            after = obj_nr_org(scatter_Id_org(n)+1:size(app.MultiCor_table.Data,1));

                            obj_nr_org = [befor(1:end-1)  obj_nr_org(scatter_Id_org(n)) befor(end) after];
                            app.MultiCor_table.Selection(n,1) = app.MultiCor_table.Selection(n,1)-1;

                            all_names = get(all_lines, 'DisplayName');
                            if ischar(all_names)
                                all_names = {all_names};  % ensure cell array
                            end

                            B=all_names;
                            A=app.MultiCor_table.Data.Name(obj_nr_org);
                            [~, idx] = ismember(B, A);
                            [xx,idx]=sort(idx);
                            all_lines=all_lines(idx);
                        else
                            max_val = max_val+1;
                        end
                    end
                    app.MinPlotX.MinPlotXFigures.hFig(app.active_plot).ax2plot.Children(ismember(app.MinPlotX.MinPlotXFigures.hFig(app.active_plot).ax2plot.Children,all_lines)) = flipud(all_lines);
                    update_ScatterObject_table(app)
                end
            end


        end

        % Button pushed function: MoveDownButton
        function MoveDownButtonPushed(app, event)
            scatter_Id_org = unique(app.MultiCor_table.Selection(:,1));
            app.MultiCor_table.Selection(:,1) = sort(app.MultiCor_table.Selection(:,1),'descend');

            children = app.MinPlotX.MinPlotXFigures.hFig(app.active_plot).ax2plot.Children;             scatter_obj_org = flipud(children(strcmp(get(children,'Type'),'scatter')));
            if not(isempty(scatter_obj_org))

                sc_obj_nr_org = 1:numel(scatter_obj_org);

                max_val = size(app.MultiCor_table.Data,1);

                for n = numel(app.MultiCor_table.Selection(:,1)):-1:1
                    if app.MultiCor_table.Selection(n,1)<max_val

                        befor = sc_obj_nr_org(1:(scatter_Id_org(n)-1));

                        after = sc_obj_nr_org(scatter_Id_org(n)+1:size(app.MultiCor_table.Data,1));

                        sc_obj_nr_org = [befor after(1) sc_obj_nr_org(scatter_Id_org(n)) after(2:end)];
                        app.MultiCor_table.Selection(n,1) = app.MultiCor_table.Selection(n,1)+1;

                    else
                        max_val = max_val-1;
                    end
                    scatter_obj_org = scatter_obj_org(sc_obj_nr_org);

                end
                app.MinPlotX.MinPlotXFigures.hFig(app.active_plot).ax2plot.Children(ismember(app.MinPlotX.MinPlotXFigures.hFig(app.active_plot).ax2plot.Children,scatter_obj_org)) = flipud(scatter_obj_org);
                update_ScatterObject_table(app)
            end

            [line_obj,all_lines] = get_line_objects(app,app.MinPlotX.MinPlotXFigures.hFig(app.active_plot).ax2plot);
            if not(isempty(line_obj))
                line_obj_nr_org = 1:numel(all_lines);

                max_val = size(app.MultiCor_table.Data,1);

                for n = numel(app.MultiCor_table.Selection(:,1)):-1:1
                    if app.MultiCor_table.Selection(n,1)<max_val

                        befor = line_obj_nr_org(1:(scatter_Id_org(n)-1));

                        after = line_obj_nr_org(scatter_Id_org(n)+1:size(app.MultiCor_table.Data,1));

                        line_obj_nr_org = [befor after(1) line_obj_nr_org(scatter_Id_org(n)) after(2:end)];
                        app.MultiCor_table.Selection(n,1) = app.MultiCor_table.Selection(n,1)+1;
                        all_names = get(all_lines, 'DisplayName');
                        if ischar(all_names)
                            all_names = {all_names};  % ensure cell array
                        end

                        B=all_names;
                        A=app.MultiCor_table.Data.Name(line_obj_nr_org);
                        [~, idx] = ismember(B, A);
                        [xx,idx]=sort(idx);
                        all_lines=all_lines(idx);
                    else
                        max_val = max_val+1;
                    end
                end
                app.MinPlotX.MinPlotXFigures.hFig(app.active_plot).ax2plot.Children(ismember(app.MinPlotX.MinPlotXFigures.hFig(app.active_plot).ax2plot.Children,all_lines)) = flipud(all_lines);
                update_ScatterObject_table(app)
            end

        end

        % Button pushed function: FlipAllButton
        function FlipAllButtonPushed(app, event)

            children = app.MinPlotX.MinPlotXFigures.hFig(app.active_plot).ax2plot.Children;             scatter_obj = flipud(children(strcmp(get(children,'Type'),'scatter')));

            if not(isempty(scatter_obj))
                app.MinPlotX.MinPlotXFigures.hFig(app.active_plot).ax2plot.Children(ismember(app.MinPlotX.MinPlotXFigures.hFig(app.active_plot).ax2plot.Children,scatter_obj)) = (scatter_obj);
            end

            [line_obj,all_lines] = get_line_objects(app,app.MinPlotX.MinPlotXFigures.hFig(app.active_plot).ax2plot);
            if not(isempty(line_obj))
                app.MinPlotX.MinPlotXFigures.hFig(app.active_plot).ax2plot.Children(ismember(app.MinPlotX.MinPlotXFigures.hFig(app.active_plot).ax2plot.Children,all_lines)) = (all_lines);
            end
            update_ScatterObject_table(app)

        end

        % Button pushed function: FlipSelectionButton
        function FlipSelectionButtonPushed(app, event)
            Id_org = unique(app.MultiCor_table.Selection(:,1));
            children = app.MinPlotX.MinPlotXFigures.hFig(app.active_plot).ax2plot.Children;             scatter_obj = flipud(children(strcmp(get(children,'Type'),'scatter')));
            if not(isempty(scatter_obj))
                scatter_obj(Id_org) = flip(scatter_obj(Id_org));
                app.MinPlotX.MinPlotXFigures.hFig(app.active_plot).ax2plot.Children(ismember(app.MinPlotX.MinPlotXFigures.hFig(app.active_plot).ax2plot.Children,scatter_obj)) = flipud(scatter_obj);
            end


            [line_obj,all_lines] = get_line_objects(app,app.MinPlotX.MinPlotXFigures.hFig(app.active_plot).ax2plot);
            if not(isempty(line_obj))
                all_names = get(all_lines, 'DisplayName');
                if ischar(all_names)
                    all_names = {all_names};  % ensure cell array
                end
                target_name = app.MultiCor_table.Data.Name(Id_org);

                % Find matching lines
                idx = find(contains(all_names, target_name));
                app.MinPlotX.MinPlotXFigures.hFig(app.active_plot).ax2plot.Children(ismember(app.MinPlotX.MinPlotXFigures.hFig(app.active_plot).ax2plot.Children,all_lines(idx))) = (all_lines(idx));
            end

            update_ScatterObject_table(app)

        end

        % Button pushed function: ExportStyleButton
        function ExportStyleButtonPushed(app, event)
            if isempty(app.MultiCor_table.Data)%;(app.active_plot)
                return
            end

            properties_lines={'DisplayName' ; 'Visible'; 'Color'  ; 'LineStyle'  ; 'LineWidth'  ; 'LineAlpha'; 'Marker' ; 'MarkerEdgeColor' ; 'MarkerFaceColor'  ; 'MarkerSize' };
            properties_scatter = {'Marker'; 'SizeData';'MarkerFaceColor';'MarkerFaceAlpha';'MarkerEdgeColor';'MarkerEdgeAlpha';'LineWidth'};


            figtmp=figure(Visible="off");
            l=plot(1,1,'MarkerEdgeColor',lines(1));

            T=table;
            T.PlotType(1:numel([properties_lines; properties_scatter]))={''};
            T.PlotType(1:numel(properties_lines))={'Line'};
            T.PlotType(numel(properties_lines)+1:end)={'Scatter'};
            T.PlotType(1:2)={'All'};

            T.PropertyName=[properties_lines; properties_scatter];
            for n=1:numel(properties_lines)
                if strcmp('LineAlpha',properties_lines(n))
                    T.DefaultValues(n)={1};
                else
                    T.DefaultValues(n)={l.(char(properties_lines(n)))};
                end
            end
            T.DefaultValues(1)={'Default'};


            s=scatter(1,1,"filled","o","MarkerFaceColor",lines(1),'MarkerEdgeColor',lines(1));

            for n=(1:numel(properties_scatter))
                T.DefaultValues(n+numel(properties_lines))={s.(char(properties_scatter(n)))};
            end

            delete(figtmp)

            %             children = app.MinPlotX.MinPlotXFigures.hFig(app.active_plot).ax2plot.Children;             scatter_obj = flipud(children(strcmp(get(children,'Type'),'scatter')));
            %             if ~isempty(scatter_obj)
            %                 keyboard
            %             end

            line_obj = get_line_objects(app,app.MinPlotX.MinPlotXFigures.hFig(app.active_plot).ax2plot);
            if ~isempty(line_obj)
                val_names = arrayfun(@(x) sprintf('Values_%02d', x), 1:numel(line_obj), 'UniformOutput', false);

                newColIds=size(T,2)+1:size(T,2)+numel(val_names);
                T(1,newColIds)=val_names;
                T=[T(:,1:2) repmat(T(:,3),1,numel(newColIds)+1)];
                T.Properties.VariableNames(newColIds)=val_names;

                try
                    for n=1:numel(properties_lines)
                        for nn=1:numel(newColIds)
                            if strcmp('Color',properties_lines(n))
                                cvals =line_obj(nn).Color;
                                T.(newColIds(nn))(n)={cvals};
                                T.(newColIds(nn))(strcmp(T.PlotType,'Scatter')& strcmp(T.PropertyName,'MarkerFaceColor'))={cvals};
                            elseif strcmp('LineAlpha',properties_lines(n))

                                if isfield(line_obj(nn).UserData,'LineAlpha')
                                    linealphaval=line_obj(nn).UserData.LineAlpha;
                                else
                                    linealphaval=1;
                                end


                                T.(newColIds(nn))(n)={linealphaval};
                                T.(newColIds(nn))(strcmp(T.PlotType,'Scatter')& strcmp(T.PropertyName,'MarkerFaceAlpha'))={linealphaval};
                                T.(newColIds(nn))(strcmp(T.PlotType,'Scatter')& strcmp(T.PropertyName,'MarkerEdgeAlpha'))={linealphaval};

                            else
                                T.(newColIds(nn))(n)={line_obj(nn).(char(properties_lines(n)))};
                            end

                            if any(ismember(properties_scatter,properties_lines(n)))
                                if strcmp(properties_lines(n),'MarkerEdgeColor') && ischar(line_obj(nn).(char( properties_lines(n))))
                                    T.(newColIds(nn))(strcmp(T.PlotType,'Scatter')& strcmp(T.PropertyName, properties_lines(n)))={line_obj(nn).Color(1:3)};
                                else
                                    T.(newColIds(nn))(strcmp(T.PlotType,'Scatter')& strcmp(T.PropertyName, properties_lines(n)))={line_obj(nn).(char( properties_lines(n)))};
                                end
                            end

                        end


                    end
                    % {line_obj.DisplayName}

                catch ME

                    keyboard
                end
            end
            children = app.MinPlotX.MinPlotXFigures.hFig(app.active_plot).ax2plot.Children;             scatter_obj = flipud(children(strcmp(get(children,'Type'),'scatter')));

            if ~isempty(scatter_obj)
                properties_lines={ 'Color'  ; 'LineAlpha' ; 'LineStyle'  ; 'LineWidth' ; 'Marker' ; 'MarkerEdgeColor' ; 'MarkerFaceColor'  ; 'MarkerSize' };
                properties_scatter = {'DisplayName' ; 'Visible';'Marker'; 'SizeData';'MarkerFaceColor';'MarkerFaceAlpha';'MarkerEdgeColor';'MarkerEdgeAlpha';'LineWidth'};
                T=[T(strcmp(T.PlotType,'All'),:); T(strcmp(T.PlotType,'Scatter'),:); T(strcmp(T.PlotType,'Line'),:)];

                val_names = arrayfun(@(x) sprintf('Values_%02d', x), 1:numel( scatter_obj), 'UniformOutput', false);

                newColIds=size(T,2)+1:size(T,2)+numel(val_names);
                T(1,newColIds)=val_names;
                T=[T(:,1:2) repmat(T(:,3),1,numel(newColIds)+1)];
                T.Properties.VariableNames(newColIds)=val_names;

                try
                    for n=1:numel(properties_scatter)
                        for nn=1:numel(newColIds)
                            if strcmp('MarkerFaceColor', properties_scatter(n))
                                T.(newColIds(nn))(n)={scatter_obj(nn).(char( properties_scatter(n)))};
                                T.(newColIds(nn))(strcmp(T.PlotType,'Line')& strcmp(T.PropertyName,'Color'))={scatter_obj(nn).(char( properties_scatter(n)))};
                            elseif strcmp('MarkerFaceAlpha', properties_scatter(n))
                                T.(newColIds(nn))(n)={scatter_obj(nn).(char( properties_scatter(n)))};
                                T.(newColIds(nn))(strcmp(T.PlotType,'Line')& strcmp(T.PropertyName,'LineAlpha'))={scatter_obj(nn).(char( properties_scatter(n)))};
                            else
                                T.(newColIds(nn))(n)={scatter_obj(nn).(char( properties_scatter(n)))};
                            end
                            if any(ismember(properties_lines, properties_scatter(n)))
                                T.(newColIds(nn))(strcmp(T.PlotType,'Line')& strcmp(T.PropertyName, properties_scatter(n)))={scatter_obj(nn).(char( properties_scatter(n)))};            end
                        end
                    end
                catch ME
                    keyboard
                end
            end

            folder = pwd;
            filename=[char(app.MinPlotX.MinPlotXFigures.hFig(app.active_plot).options.type.Value) '_PlotStyle'];
            f = figure('Renderer', 'painters', 'Position', [-100 -100 0 0]);
            [file, folder] = uiputfile(fullfile(folder, '*.csv'), 'Save Style Table',filename);
            if file == 0
                return;
            end
            delete(f);
            figure(app.EasyPlotModuleUIFigure);
            [~, FileNameNoExt, ~] = fileparts(file);
            fullFileName = fullfile(folder, [FileNameNoExt, '.csv']);

            T_out = T;

            for i = 1:width(T_out)
                colData = T_out{:,i};  % this is cell if column type is cell, numeric if numeric

                if isa(colData, 'matlab.lang.OnOffSwitchState')
                    % If entire column is OnOffSwitchState, convert to logical numeric array
                    T_out{:,i} = double(colData == matlab.lang.OnOffSwitchState.on);

                elseif iscell(colData)
                    % Column is a cell array
                    for r = 1:numel(colData)
                        x = colData{r};
                        if isnumeric(x) && numel(x) > 1
                            colData{r} = mat2str(round(x,4));
                        elseif isa(x, 'matlab.lang.OnOffSwitchState')
                            % Convert to double 0/1 inside cell
                            colData{r} = double(x == matlab.lang.OnOffSwitchState.on);
                        end
                    end
                    T_out{:,i} = colData;  % assign modified cell array back

                end
            end

            writetable(T_out, fullFileName);

        end

        % Button pushed function: ReadStyleButton
        function ReadStyleButtonPushed(app, event)

            %%
            f = figure('Renderer', 'painters', 'Position', [-100 -100 0 0]);
            [file,path] = uigetfile({'*.csv'},"MultiSelect","off", 'Import Style Table');
            delete(f);
            figure(app.EasyPlotModuleUIFigure);

            if isequal(file,0)
                return
            end

            %%%
            % Read CSV as raw cell array
            C = readcell(fullfile(path,file), 'TextType', 'string');
            VarNames = C(1,:);
            C = C(2:end,:);  % Remove header

            % Define property classifications
            properties_lines = {'Color'; 'LineStyle'; 'LineWidth'; 'LineAlpha'; 'Marker'; ...
                'MarkerEdgeColor'; 'MarkerFaceColor'; 'MarkerSize'};
            properties_scatter = {'DisplayName'; 'Visible'; 'Marker'; 'SizeData'; ...
                'MarkerFaceColor'; 'MarkerFaceAlpha'; 'MarkerEdgeColor'; ...
                'MarkerEdgeAlpha'; 'LineWidth'};

            prop_array   = intersect([properties_lines; properties_scatter], ...
                {'Color','MarkerEdgeColor','MarkerFaceColor'});
            prop_numeric = intersect([properties_lines; properties_scatter], ...
                {'LineAlpha','LineWidth','MarkerSize','SizeData', ...
                'MarkerFaceAlpha','MarkerEdgeAlpha','LineAlpha'});
            prop_logical = {'Visible'};

            % Loop over each row and column 3:end
            for i = 1:size(C,1)
                prop = C{i,2};  % Assume PropertyName is in column 2
                for j = 3:size(C,2)
                    val = C{i,j};

                    if ismember(prop, prop_array) && isstring(val) && startsWith(val, "[")
                        C{i,j} = sscanf(val, '[%f %f %f %f %f %f %f %f %f %f]');
                    elseif ismember(prop, prop_numeric) && isstring(val)
                        C{i,j} = str2double(val);
                    elseif ismember(prop, prop_logical)
                        if (isnumeric(val) && val==1) || (ischar(val)&&strcmpi(val,"true"))
                            C{i,j} = 'on';
                        elseif (isnumeric(val) && val==0) || (ischar(val)&&strcmpi(val,"false"))
                            C{i,j} = 'off';
                        end
                    end
                end
            end

            % Convert to table
            T = cell2table(C);
            T.Properties.VariableNames = string(VarNames);


            %%%
            T_scatter=[T(strcmp(T.PlotType,'All'),:); T(strcmp(T.PlotType,'Scatter'),:)];
            T_lines=[T(strcmp(T.PlotType,'All'),:); T(strcmp(T.PlotType,'Line'),:)];


            d = uiprogressdlg(app.EasyPlotModuleUIFigure,'Title','Applying Changes...',...
                'Message','Progress Started','Cancelable','on');
            w2c = {'Visible'; 'Marker'; 'SizeData';'MarkerFaceColor';'MarkerFaceAlpha';'MarkerEdgeColor';'MarkerEdgeAlpha';'LineWidth';'Legend'};


            names=T(strcmp(T.PropertyName,'DisplayName'),3:end);

            for n = 1:numel(app.MinPlotX.MinPlotXFigures.hFig)
                d.Value = n/numel(app.MinPlotX.MinPlotXFigures.hFig);
                d.Message = sprintf(['Changing appearance in figure: ' num2str(n) ' of ' num2str(numel(app.MinPlotX.MinPlotXFigures.hFig))]);
                if d.CancelRequested
                    break
                end
                children = app.MinPlotX.MinPlotXFigures.hFig(n).ax2plot.Children;                                             scatter_obj = flipud(children(strcmp(get(children,'Type'),'scatter')));


                if ~isempty(scatter_obj)
                    for n_SID = 1:numel(names)

                        Name = string(names.(n_SID));
                        if any(strcmp({scatter_obj.DisplayName},Name))
                            scatter_Id = find(strcmp({scatter_obj.DisplayName},Name));
                            for l = 1:numel(scatter_Id)
                                p2check=[2; find(strcmp(T.PlotType,'Scatter'))];
                                for u= 1:numel(p2check)
                                    try
                                        newval=C{(strcmp(T.PlotType,'Scatter')|strcmp(T.PlotType,'All'))&strcmp(T.PropertyName,char(T.PropertyName(p2check(u)))),ismember(cellfun(@char,(table2cell(T(strcmp(T.PropertyName,'DisplayName'),:))),'UniformOutput',false),Name)};
                                        if ~(strcmp(T.PropertyName(p2check(u)),'MarkerEdgeColor') && strcmp(newval,'auto'))
                                            scatter_obj(scatter_Id(l)).(char(T.PropertyName(p2check(u))))=newval;
                                        else
                                            scatter_obj(scatter_Id(l)).(char(T.PropertyName(p2check(u))))=C((strcmp(T.PlotType,'Line')|strcmp(T.PlotType,'All'))&strcmp(T.PropertyName,char('Color')),ismember(cellfun(@char,(table2cell(T(strcmp(T.PropertyName,'DisplayName'),:))),'UniformOutput',false),Name));
                                        end
                                    catch
                                        keyboard
                                    end
                                end
                            end
                        end

                    end


                end

                [line_obj,all_lines] = get_line_objects(app,app.MinPlotX.MinPlotXFigures.hFig(app.active_plot).ax2plot);
                if not(isempty(line_obj))
                    all_names = get(all_lines, 'DisplayName');
                    try
                        for n_SID = 1:numel(names)
                            Name = string(names.(n_SID));
                            if any(strcmp({all_lines.DisplayName},Name))
                                scatter_Id = find(strcmp({all_lines.DisplayName},Name));
                                for l = 1:numel(scatter_Id)
                                    p2check=[2; find(strcmp(T.PlotType,'Line'))];
                                    for u= 1:numel(p2check)
                                        if strcmp(char(T.PropertyName(p2check(u))),'LineAlpha')
                                            all_lines(scatter_Id(l)).Color(4)=C{(strcmp(T.PlotType,'Line')|strcmp(T.PlotType,'All'))&strcmp(T.PropertyName,char(T.PropertyName(p2check(u)))),ismember(cellfun(@char,(table2cell(T(strcmp(T.PropertyName,'DisplayName'),:))),'UniformOutput',false),Name)};
                                            all_lines(scatter_Id(l)).UserData.LineAlpha=C{(strcmp(T.PlotType,'Line')|strcmp(T.PlotType,'All'))&strcmp(T.PropertyName,char(T.PropertyName(p2check(u)))),ismember(cellfun(@char,(table2cell(T(strcmp(T.PropertyName,'DisplayName'),:))),'UniformOutput',false),Name)};

                                        else
                                            all_lines(scatter_Id(l)).(char(T.PropertyName(p2check(u))))=C{(strcmp(T.PlotType,'Line')|strcmp(T.PlotType,'All'))&strcmp(T.PropertyName,char(T.PropertyName(p2check(u)))),ismember(cellfun(@char,(table2cell(T(strcmp(T.PropertyName,'DisplayName'),:))),'UniformOutput',false),Name)};
                                        end
                                    end
                                end
                            end
                        end

                    catch ME
                        ME.stack.line
                        keyboard
                    end
                end
            end

            update_ScatterObject_table(app)

        end

        % Button pushed function: ReplaceStringinNameButton
        function ReplaceStringinNameButtonPushed(app, event)
            str2rmv = inputdlg('Enter string to find','Find in SampleName', 1);
            if isempty(str2rmv) || isempty(str2rmv{:})
                figure(app.EasyPlotModuleUIFigure);
                return
            end
            str2rep = inputdlg('Enter replacement','Replace with in SampleName', 1);
            figure(app.EasyPlotModuleUIFigure);

            d = uiprogressdlg(app.EasyPlotModuleUIFigure,'Title','Applying Changes...',...
                'Message','Progress Started','Cancelable','on');

            for n = 1:numel(app.MinPlotX.MinPlotXFigures.hFig)
                d.Value = n/numel(app.MinPlotX.MinPlotXFigures.hFig);
                d.Message = sprintf(['Changing Name: ' num2str(n) ' of ' num2str(numel(app.MinPlotX.MinPlotXFigures.hFig))]);
                if d.CancelRequested
                    break
                end

                children = app.MinPlotX.MinPlotXFigures.hFig(n).ax2plot.Children;                                             scatter_obj = flipud(children(strcmp(get(children,'Type'),'scatter')));
                line_obj = get_line_objects(app,app.MinPlotX.MinPlotXFigures.hFig(n).ax2plot);

                if not(isempty(scatter_obj))

                    if any(contains({scatter_obj.DisplayName},str2rmv))
                        object_Id = find(contains({scatter_obj.DisplayName},str2rmv));

                        for l = 1:numel(object_Id)
                            scatter_obj(object_Id(l)).DisplayName = replace(scatter_obj(object_Id(l)).DisplayName,str2rmv,str2rep);
                        end
                    end
                end
                if not(isempty(line_obj))

                    if any(contains({line_obj.DisplayName},str2rmv))
                        object_Id = find(contains({line_obj.DisplayName},str2rmv));

                        for l = 1:numel(object_Id)
                            line_obj(object_Id(l)).DisplayName = replace(line_obj(object_Id(l)).DisplayName,str2rmv,str2rep);
                        end
                    end


                end

            end
            update_ScatterObject_table(app)

        end

        % Button pushed function: xlinlogButton
        function xlinlogButtonPushed(app, event)
            try
                if strcmp(app.MinPlotX.MinPlotXFigures.hFig(app.active_plot).ax2plot.XScale,'log')
                    state = 'linear';
                else
                    state = 'log';

                end
                app.MinPlotX.MinPlotXFigures.hFig(app.active_plot).ax2plot.XScale = state;
            catch ME
                DisplayError(ME,'xlinlogButtonPushed Update Error')
                CustomAxLimitsCheckBoxValueChanged(app)
            end
        end

        % Button pushed function: ylinlogButton
        function ylinlogButtonPushed(app, event)
            try
                if strcmp(app.MinPlotX.MinPlotXFigures.hFig(app.active_plot).ax2plot.YScale,'log')
                    state = 'linear';
                else
                    state = 'log';

                end
                app.MinPlotX.MinPlotXFigures.hFig(app.active_plot).ax2plot.YScale = state;
            catch ME
                DisplayError(ME,'ylinlogButtonPushed Update Error')
                CustomAxLimitsCheckBoxValueChanged(app)
            end


        end

        % Button pushed function: xgridButton
        function xgridButtonPushed(app, event)
            try
                if strcmp(app.MinPlotX.MinPlotXFigures.hFig(app.active_plot).ax2plot.XGrid,'off')
                    state = 'on';
                else
                    state = 'off';
                end

                app.MinPlotX.MinPlotXFigures.hFig(app.active_plot).ax2plot.XMinorGrid = state;
                app.MinPlotX.MinPlotXFigures.hFig(app.active_plot).ax2plot.XGrid = state;
            catch ME
                DisplayError(ME,'xgridButtonPushed Update Error')
                CustomAxLimitsCheckBoxValueChanged(app)
            end
        end

        % Button pushed function: ygridButton
        function ygridButtonPushed(app, event)
            try
                if strcmp(app.MinPlotX.MinPlotXFigures.hFig(app.active_plot).ax2plot.YGrid,'off')
                    state = 'on';
                else
                    state = 'off';
                end

                app.MinPlotX.MinPlotXFigures.hFig(app.active_plot).ax2plot.YMinorGrid = state;
                app.MinPlotX.MinPlotXFigures.hFig(app.active_plot).ax2plot.YGrid = state;
            catch ME
                DisplayError(ME,'xgridButtonPushed Update Error')
                CustomAxLimitsCheckBoxValueChanged(app)
            end
        end

        % Button pushed function: InspectAllPropertiesButton
        function InspectAllPropertiesButtonPushed(app, event)
            inspect(app.MinPlotX.MinPlotXFigures.hFig(app.active_plot).ax2plot)


        end

        % Value changed function: ColorbarLabelEditField, 
        % ColorbarLabelInterpreterDropDown, FigureFontSizeSpinner, 
        % FontDropDown, LegendMarkerSizeSpinner, XAxesPosDropDown, 
        % XDirDropDown, XGridDropDown, XLabelEditField, 
        % XLabelInterpreterDropDown, XLimitsEditField_max, 
        % XLimitsEditField_min, XScaleDropDown, YAxesPosDropDown, 
        % YDirDropDown, YGridDropDown, YLabelEditField, 
        % YLabelInterpreterDropDown, YLimitsEditField_max, 
        % YLimitsEditField_min, YScaleDropDown, ZDirDropDown, 
        % ZGridDropDown, ZLabelEditField, ZLabelInterpreterDropDown, 
        % ZLimitsEditField_max, ZLimitsEditField_min, ZScaleDropDown
        function AaFigureFontSizeSpinnerValueChanged(app, event)
            if not(any(ismember(fieldnames(app.MinPlotX),'MinPlotXFigures'))) || isempty(app.MinPlotX.MinPlotXFigures) ||not(isfield(app.MinPlotX.MinPlotXFigures,'hFig_type')) || isempty(app.MinPlotX.MinPlotXFigures.hFig_type)
                return
            end

            ax2plot = app.MinPlotX.MinPlotXFigures.hFig(app.active_plot).ax2plot;
            app.MinPlotX.MinPlotXFigures.hFig(app.active_plot).ax2plot.UserData.LegendMarkerSize = app.LegendMarkerSizeSpinner.Value;

            edit_fig_props(app,ax2plot)
        end

        % Button pushed function: AutoButton
        function AutoButtonPushed(app, event)

            ax2plot = app.MinPlotX.MinPlotXFigures.hFig(app.active_plot).ax2plot;
            ax2plot.Legend.NumColumnsMode='auto';
            app.NumberofcolumnsSpinner.Value=ax2plot.Legend.NumColumns;


            %  set_background_button=ax2plot.Legend.Color /
            %  EdgeColor / LineWidth / TextColor


        end

        % Button pushed function: MultiPlotButton
        function MultiPlotButtonPushed(app, event)


            % --- create new figure ---
            hFig_new = figure('Name','Combined Plot','Color','w');

            % number of plots
            N = numel(app.MinPlotX.MinPlotXFigures.hFig);

            % define tiled layout (auto grid)
            t = tiledlayout(hFig_new, 'flow');

            for n = 1:N

                % --- ensure plot exists ---
                if ~strcmp(app.Tree.Children(n).NodeData, app.MinPlotX.MinPlotXFigures.hFig_type{n})
                    MinPlotXEasyPlot_public_update(app)
                end

                % --- get original axes ---
                hAx_old = app.MinPlotX.MinPlotXFigures.hFig(n).ax2plot(1);

                % --- create next tile ---
                ax_new = nexttile(t);

                % --- copy all children (plots, images, etc.) ---
                copyobj(allchild(hAx_old), ax_new);

                % --- copy all compatible axes properties ---
                props = properties(hAx_old);

                % properties to ignore (critical!)
                % ignoreProps = {
                %     'Parent','Children','Position','OuterPosition','TightInset',...
                %     'Layout','ActivePositionProperty'
                % };

                ignoreProps = {'CurrentPoint';'Parent';'Children';'XAxis';'YAxis';'ZAxis';'Position';'OuterPosition';'InnerPosition';...
                    'PositionConstraint';'Units';'Toolbar';'Layout';'BeingDeleted';'Type'};


                for i = 1:numel(props)
                    propName = props{i};

                    if ismember(propName, ignoreProps)
                        continue
                    end

                    try
                        tmp_prop= hAx_old.(propName);

                        if ismember(propName, {'XLabel','YLabel','ZLabel','Title'})

                            oldObj = tmp_prop;
                            newObj = ax_new.(propName);

                            % copy relevant text properties
                            newObj.String   = oldObj.String;
                            newObj.FontSize = oldObj.FontSize;
                            newObj.FontName = oldObj.FontName;
                            newObj.Rotation = oldObj.Rotation;
                            newObj.Interpreter = oldObj.Interpreter;

                            continue
                        end

                        %     hAx_old.(propName)= tmp_prop;

                        if ismember(propName, 'FontSize')
                            tmp_prop=tmp_prop/1.5;
                        end
                        ax_new.(propName) = tmp_prop;
                    catch
                        % silently skip incompatible properties
                    end
                end
                % --- optional: colormap (important for imagesc) ---
                colormap(ax_new, colormap(hAx_old));

            end

            %%
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.EasyPlotModuleUIFigure = uifigure('Visible', 'off');
            app.EasyPlotModuleUIFigure.Name = 'EasyPlotModule';
            app.GridLayout2 = uigridlayout(app.EasyPlotModuleUIFigure);
            app.TabGroup = uitabgroup(app.GridLayout2);
            app.PlotSettingsTab = uitab(app.TabGroup);
            app.PlotSettingsTab.Title = 'PlotSettings';
            app.GridLayout5 = uigridlayout(app.PlotSettingsTab);
            app.TabGroup2 = uitabgroup(app.GridLayout5);
            app.DataSettingsTab = uitab(app.TabGroup2);
            app.DataSettingsTab.Title = 'Data Settings';
            app.GridLayout4 = uigridlayout(app.DataSettingsTab);
            app.MultiCor_table = uitable(app.GridLayout4);
            app.MultiCor_table.ColumnName = {'Active'; 'Name'; 'Symbol'; 'SymbolSize'; 'MarkerFaceColor'};
            app.MultiCor_table.RowName = {};
            app.MultiCor_table.ColumnSortable = [true true true true true false true];
            app.MultiCor_table.ColumnEditable = [true false true true true false false];
            app.MultiCor_table.CellEditCallback = createCallbackFcn(app, @MultiCor_tableCellEdit, true);
            app.MultiCor_table.ButtonDownFcn = createCallbackFcn(app, @MultiCor_tableButtonDown, true);
            app.RemoveSelectedButton = uibutton(app.GridLayout4, 'push');
            app.RemoveSelectedButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveSelectedButtonPushed, true);
            app.RemoveSelectedButton.Text = 'Remove Selected';
            app.ShowLegendCheckBox = uicheckbox(app.GridLayout4);
            app.ShowLegendCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowLegendCheckBoxValueChanged, true);
            app.ShowLegendCheckBox.Text = 'Show Legend';
            app.ShowLegendCheckBox.Value = true;
            app.AdvancedSettingsCheckBox = uicheckbox(app.GridLayout4);
            app.AdvancedSettingsCheckBox.ValueChangedFcn = createCallbackFcn(app, @AdvancedSettingsCheckBoxValueChanged, true);
            app.AdvancedSettingsCheckBox.Text = 'Advanced Settings';
            app.ColorPreviewCheckBox = uicheckbox(app.GridLayout4);
            app.ColorPreviewCheckBox.ValueChangedFcn = createCallbackFcn(app, @ColorPreviewCheckBoxValueChanged, true);
            app.ColorPreviewCheckBox.Text = 'Color Preview';
            app.FlipSelectionButton = uibutton(app.GridLayout4, 'push');
            app.FlipSelectionButton.ButtonPushedFcn = createCallbackFcn(app, @FlipSelectionButtonPushed, true);
            app.FlipSelectionButton.Visible = 'off';
            app.FlipSelectionButton.Text = 'Flip Selection';
            app.MoveDownButton = uibutton(app.GridLayout4, 'push');
            app.MoveDownButton.ButtonPushedFcn = createCallbackFcn(app, @MoveDownButtonPushed, true);
            app.MoveDownButton.Visible = 'off';
            app.MoveDownButton.Text = 'Move Down';
            app.FlipAllButton = uibutton(app.GridLayout4, 'push');
            app.FlipAllButton.ButtonPushedFcn = createCallbackFcn(app, @FlipAllButtonPushed, true);
            app.FlipAllButton.Visible = 'off';
            app.FlipAllButton.Text = 'Flip All';
            app.MoveUpButton = uibutton(app.GridLayout4, 'push');
            app.MoveUpButton.ButtonPushedFcn = createCallbackFcn(app, @MoveUpButtonPushed, true);
            app.MoveUpButton.Visible = 'off';
            app.MoveUpButton.Text = 'Move Up';
            app.MergeSelectionButton = uibutton(app.GridLayout4, 'push');
            app.MergeSelectionButton.ButtonPushedFcn = createCallbackFcn(app, @MergeSelectionButtonPushed, true);
            app.MergeSelectionButton.Visible = 'off';
            app.MergeSelectionButton.Text = 'Merge Selection';
            app.ApplyGlobaltoselectionButton = uibutton(app.GridLayout4, 'push');
            app.ApplyGlobaltoselectionButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyGlobaltoselectionMenuSelected, true);
            app.ApplyGlobaltoselectionButton.Visible = 'off';
            app.ApplyGlobaltoselectionButton.Text = 'Apply Global to selection';
            app.ApplyGlobalButton = uibutton(app.GridLayout4, 'push');
            app.ApplyGlobalButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyGlobalMenuSelected, true);
            app.ApplyGlobalButton.Visible = 'off';
            app.ApplyGlobalButton.Text = 'Apply Global';
            app.ExportStyleButton = uibutton(app.GridLayout4, 'push');
            app.ExportStyleButton.ButtonPushedFcn = createCallbackFcn(app, @ExportStyleButtonPushed, true);
            app.ExportStyleButton.Visible = 'off';
            app.ExportStyleButton.Text = 'Export Style';
            app.ReadStyleButton = uibutton(app.GridLayout4, 'push');
            app.ReadStyleButton.ButtonPushedFcn = createCallbackFcn(app, @ReadStyleButtonPushed, true);
            app.ReadStyleButton.Visible = 'off';
            app.ReadStyleButton.Text = 'Read Style';
            app.ReplaceStringinNameButton = uibutton(app.GridLayout4, 'push');
            app.ReplaceStringinNameButton.ButtonPushedFcn = createCallbackFcn(app, @ReplaceStringinNameButtonPushed, true);
            app.ReplaceStringinNameButton.Visible = 'off';
            app.ReplaceStringinNameButton.Text = 'Replace String in Name';
            app.LegendInterpreterDropDownLabel = uilabel(app.GridLayout4);
            app.LegendInterpreterDropDownLabel.Text = 'Legend Interpreter';
            app.LegendInterpreterDropDown = uidropdown(app.GridLayout4);
            app.LegendInterpreterDropDown.Items = {'none', 'tex', 'latex'};
            app.LegendInterpreterDropDown.ValueChangedFcn = createCallbackFcn(app, @LegendInterpreterDropDownValueChanged, true);
            app.LegendInterpreterDropDown.Value = 'none';
            app.MultiPlotButton = uibutton(app.GridLayout4, 'push');
            app.MultiPlotButton.ButtonPushedFcn = createCallbackFcn(app, @MultiPlotButtonPushed, true);
            app.MultiPlotButton.Text = 'MultiPlot';
            app.FigureSettingsTab = uitab(app.TabGroup2);
            app.FigureSettingsTab.Title = 'Figure Settings';
            app.GridLayout6 = uigridlayout(app.FigureSettingsTab);
            app.InspectAllPropertiesButton = uibutton(app.GridLayout6, 'push');
            app.InspectAllPropertiesButton.ButtonPushedFcn = createCallbackFcn(app, @InspectAllPropertiesButtonPushed, true);
            app.InspectAllPropertiesButton.Text = 'Inspect All Properties';
            app.YLabelInterpreterDropDown_Label = uilabel(app.GridLayout6);
            app.YLabelInterpreterDropDown_Label.Text = 'Y Label Interpreter';
            app.YLabelInterpreterDropDown = uidropdown(app.GridLayout6);
            app.YLabelInterpreterDropDown.Items = {'none', 'tex', 'latex'};
            app.YLabelInterpreterDropDown.ValueChangedFcn = createCallbackFcn(app, @AaFigureFontSizeSpinnerValueChanged, true);
            app.YLabelInterpreterDropDown.Value = 'none';
            app.ZLabelInterpreterDropDown_Label = uilabel(app.GridLayout6);
            app.ZLabelInterpreterDropDown_Label.Text = 'Z Label Interpreter';
            app.ZLabelInterpreterDropDown = uidropdown(app.GridLayout6);
            app.ZLabelInterpreterDropDown.Items = {'none', 'tex', 'latex'};
            app.ZLabelInterpreterDropDown.ValueChangedFcn = createCallbackFcn(app, @AaFigureFontSizeSpinnerValueChanged, true);
            app.ZLabelInterpreterDropDown.Value = 'none';
            app.ColorbarLabelInterpreterDropDown_Label = uilabel(app.GridLayout6);
            app.ColorbarLabelInterpreterDropDown_Label.Text = 'C Label Interpreter';
            app.ColorbarLabelInterpreterDropDown = uidropdown(app.GridLayout6);
            app.ColorbarLabelInterpreterDropDown.Items = {'none', 'tex', 'latex'};
            app.ColorbarLabelInterpreterDropDown.ValueChangedFcn = createCallbackFcn(app, @AaFigureFontSizeSpinnerValueChanged, true);
            app.ColorbarLabelInterpreterDropDown.Value = 'none';
            app.FigureFontSizeSpinnerLabel = uilabel(app.GridLayout6);
            app.FigureFontSizeSpinnerLabel.Text = 'Figure FontSize';
            app.FigureFontSizeSpinner = uispinner(app.GridLayout6);
            app.FigureFontSizeSpinner.Limits = [1 Inf];
            app.FigureFontSizeSpinner.ValueChangedFcn = createCallbackFcn(app, @AaFigureFontSizeSpinnerValueChanged, true);
            app.FigureFontSizeSpinner.Value = 12;
            app.XLabelEditFieldLabel = uilabel(app.GridLayout6);
            app.XLabelEditFieldLabel.Text = 'X Label';
            app.XLabelEditField = uieditfield(app.GridLayout6, 'text');
            app.XLabelEditField.ValueChangedFcn = createCallbackFcn(app, @AaFigureFontSizeSpinnerValueChanged, true);
            app.YLabelEditFieldLabel = uilabel(app.GridLayout6);
            app.YLabelEditFieldLabel.Text = 'Y Label';
            app.YLabelEditField = uieditfield(app.GridLayout6, 'text');
            app.YLabelEditField.ValueChangedFcn = createCallbackFcn(app, @AaFigureFontSizeSpinnerValueChanged, true);
            app.YLabelEditField_2Label = uilabel(app.GridLayout6);
            app.YLabelEditField_2Label.Text = 'Z Label';
            app.ZLabelEditField = uieditfield(app.GridLayout6, 'text');
            app.ZLabelEditField.ValueChangedFcn = createCallbackFcn(app, @AaFigureFontSizeSpinnerValueChanged, true);
            app.ColorbarLabelEditField_Label = uilabel(app.GridLayout6);
            app.ColorbarLabelEditField_Label.Text = 'Colorbar Label';
            app.ColorbarLabelEditField = uieditfield(app.GridLayout6, 'text');
            app.ColorbarLabelEditField.ValueChangedFcn = createCallbackFcn(app, @AaFigureFontSizeSpinnerValueChanged, true);
            app.XLabelInterpreterDropDownLabel = uilabel(app.GridLayout6);
            app.XLabelInterpreterDropDownLabel.Text = 'X Label Interpreter';
            app.XLabelInterpreterDropDown = uidropdown(app.GridLayout6);
            app.XLabelInterpreterDropDown.Items = {'none', 'tex', 'latex'};
            app.XLabelInterpreterDropDown.ValueChangedFcn = createCallbackFcn(app, @AaFigureFontSizeSpinnerValueChanged, true);
            app.XLabelInterpreterDropDown.Value = 'none';
            app.FontDropDownLabel = uilabel(app.GridLayout6);
            app.FontDropDownLabel.Text = 'Font';
            app.FontDropDown = uidropdown(app.GridLayout6);
            app.FontDropDown.Items = {};
            app.FontDropDown.ValueChangedFcn = createCallbackFcn(app, @AaFigureFontSizeSpinnerValueChanged, true);
            app.FontDropDown.Value = {};
            app.XAxesPosDropDownLabel = uilabel(app.GridLayout6);
            app.XAxesPosDropDownLabel.Text = 'X Axes Pos';
            app.XAxesPosDropDown = uidropdown(app.GridLayout6);
            app.XAxesPosDropDown.Items = {'bottom', 'top', 'origin'};
            app.XAxesPosDropDown.ValueChangedFcn = createCallbackFcn(app, @AaFigureFontSizeSpinnerValueChanged, true);
            app.XAxesPosDropDown.Value = 'bottom';
            app.YAxesPosDropDownLabel = uilabel(app.GridLayout6);
            app.YAxesPosDropDownLabel.Text = 'Y Axes Pos';
            app.YAxesPosDropDown = uidropdown(app.GridLayout6);
            app.YAxesPosDropDown.Items = {'left', 'right', 'origin'};
            app.YAxesPosDropDown.ValueChangedFcn = createCallbackFcn(app, @AaFigureFontSizeSpinnerValueChanged, true);
            app.YAxesPosDropDown.Value = 'left';
            app.XDirDropDownLabel = uilabel(app.GridLayout6);
            app.XDirDropDownLabel.Text = 'XDir';
            app.XDirDropDown = uidropdown(app.GridLayout6);
            app.XDirDropDown.Items = {'normal', 'reverse'};
            app.XDirDropDown.ValueChangedFcn = createCallbackFcn(app, @AaFigureFontSizeSpinnerValueChanged, true);
            app.XDirDropDown.Value = 'normal';
            app.YDirDropDownLabel = uilabel(app.GridLayout6);
            app.YDirDropDownLabel.Text = 'YDir';
            app.YDirDropDown = uidropdown(app.GridLayout6);
            app.YDirDropDown.Items = {'normal', 'reverse'};
            app.YDirDropDown.ValueChangedFcn = createCallbackFcn(app, @AaFigureFontSizeSpinnerValueChanged, true);
            app.YDirDropDown.Value = 'normal';
            app.ZDirDropDownLabel = uilabel(app.GridLayout6);
            app.ZDirDropDownLabel.Text = 'ZDir';
            app.ZDirDropDown = uidropdown(app.GridLayout6);
            app.ZDirDropDown.Items = {'normal', 'reverse'};
            app.ZDirDropDown.ValueChangedFcn = createCallbackFcn(app, @AaFigureFontSizeSpinnerValueChanged, true);
            app.ZDirDropDown.Value = 'normal';
            app.CustomAxLimitsCheckBox = uicheckbox(app.GridLayout6);
            app.CustomAxLimitsCheckBox.ValueChangedFcn = createCallbackFcn(app, @CustomAxLimitsCheckBoxValueChanged, true);
            app.CustomAxLimitsCheckBox.Text = 'Custom Ax Limits';
            app.XScaleDropDownLabel = uilabel(app.GridLayout6);
            app.XScaleDropDownLabel.Text = 'X Scale';
            app.XScaleDropDown = uidropdown(app.GridLayout6);
            app.XScaleDropDown.Items = {'linear', 'log'};
            app.XScaleDropDown.ValueChangedFcn = createCallbackFcn(app, @AaFigureFontSizeSpinnerValueChanged, true);
            app.XScaleDropDown.Value = 'linear';
            app.YScaleDropDownLabel = uilabel(app.GridLayout6);
            app.YScaleDropDownLabel.Text = 'Y Scale';
            app.YScaleDropDown = uidropdown(app.GridLayout6);
            app.YScaleDropDown.Items = {'linear', 'log'};
            app.YScaleDropDown.ValueChangedFcn = createCallbackFcn(app, @AaFigureFontSizeSpinnerValueChanged, true);
            app.YScaleDropDown.Value = 'linear';
            app.ZScaleDropDownLabel = uilabel(app.GridLayout6);
            app.ZScaleDropDownLabel.Text = 'Z Scale';
            app.ZScaleDropDown = uidropdown(app.GridLayout6);
            app.ZScaleDropDown.Items = {'linear', 'log'};
            app.ZScaleDropDown.ValueChangedFcn = createCallbackFcn(app, @AaFigureFontSizeSpinnerValueChanged, true);
            app.ZScaleDropDown.Value = 'linear';
            app.XGridDropDownLabel = uilabel(app.GridLayout6);
            app.XGridDropDownLabel.Text = 'X Grid';
            app.XGridDropDown = uidropdown(app.GridLayout6);
            app.XGridDropDown.Items = {'off', 'on'};
            app.XGridDropDown.ValueChangedFcn = createCallbackFcn(app, @AaFigureFontSizeSpinnerValueChanged, true);
            app.XGridDropDown.Value = 'off';
            app.YGridDropDownLabel = uilabel(app.GridLayout6);
            app.YGridDropDownLabel.Text = 'Y Grid';
            app.YGridDropDown = uidropdown(app.GridLayout6);
            app.YGridDropDown.Items = {'off', 'on'};
            app.YGridDropDown.ValueChangedFcn = createCallbackFcn(app, @AaFigureFontSizeSpinnerValueChanged, true);
            app.YGridDropDown.Value = 'off';
            app.ZGridDropDownLabel = uilabel(app.GridLayout6);
            app.ZGridDropDownLabel.Text = 'Z Grid';
            app.ZGridDropDown = uidropdown(app.GridLayout6);
            app.ZGridDropDown.Items = {'off', 'on'};
            app.ZGridDropDown.ValueChangedFcn = createCallbackFcn(app, @AaFigureFontSizeSpinnerValueChanged, true);
            app.ZGridDropDown.Value = 'off';
            app.YLimitsEditFieldLabel = uilabel(app.GridLayout6);
            app.YLimitsEditFieldLabel.Visible = 'off';
            app.YLimitsEditFieldLabel.Text = 'Y Limits';
            app.YLimitsEditField_min = uieditfield(app.GridLayout6, 'numeric');
            app.YLimitsEditField_min.ValueChangedFcn = createCallbackFcn(app, @AaFigureFontSizeSpinnerValueChanged, true);
            app.YLimitsEditField_min.Visible = 'off';
            app.XLimitsEditFieldLabel = uilabel(app.GridLayout6);
            app.XLimitsEditFieldLabel.Visible = 'off';
            app.XLimitsEditFieldLabel.Text = 'X Limits';
            app.XLimitsEditField_min = uieditfield(app.GridLayout6, 'numeric');
            app.XLimitsEditField_min.ValueChangedFcn = createCallbackFcn(app, @AaFigureFontSizeSpinnerValueChanged, true);
            app.XLimitsEditField_min.Visible = 'off';
            app.YLimitsEditField_max = uieditfield(app.GridLayout6, 'numeric');
            app.YLimitsEditField_max.ValueChangedFcn = createCallbackFcn(app, @AaFigureFontSizeSpinnerValueChanged, true);
            app.YLimitsEditField_max.Visible = 'off';
            app.XLimitsEditField_max = uieditfield(app.GridLayout6, 'numeric');
            app.XLimitsEditField_max.ValueChangedFcn = createCallbackFcn(app, @AaFigureFontSizeSpinnerValueChanged, true);
            app.XLimitsEditField_max.Visible = 'off';
            app.ZLimitsEditField_max = uieditfield(app.GridLayout6, 'numeric');
            app.ZLimitsEditField_max.ValueChangedFcn = createCallbackFcn(app, @AaFigureFontSizeSpinnerValueChanged, true);
            app.ZLimitsEditField_max.Visible = 'off';
            app.ZLimitsEditFieldLabel = uilabel(app.GridLayout6);
            app.ZLimitsEditFieldLabel.Visible = 'off';
            app.ZLimitsEditFieldLabel.Text = 'Z Limits';
            app.ZLimitsEditField_min = uieditfield(app.GridLayout6, 'numeric');
            app.ZLimitsEditField_min.ValueChangedFcn = createCallbackFcn(app, @AaFigureFontSizeSpinnerValueChanged, true);
            app.ZLimitsEditField_min.Visible = 'off';
            app.LegendTab = uitab(app.TabGroup2);
            app.LegendTab.Title = 'Legend';
            app.GridLayout7 = uigridlayout(app.LegendTab);
            app.AutoButton = uibutton(app.GridLayout7, 'push');
            app.AutoButton.ButtonPushedFcn = createCallbackFcn(app, @AutoButtonPushed, true);
            app.AutoButton.Text = 'Auto';
            app.ShowLegendCheckBox_2 = uicheckbox(app.GridLayout7);
            app.ShowLegendCheckBox_2.ValueChangedFcn = createCallbackFcn(app, @ShowLegendCheckBoxValueChanged, true);
            app.ShowLegendCheckBox_2.Text = 'Show Legend';
            app.ShowLegendCheckBox_2.Value = true;
            app.LocationDropDownLabel = uilabel(app.GridLayout7);
            app.LocationDropDownLabel.Text = 'Location';
            app.LocationDropDown = uidropdown(app.GridLayout7);
            app.LocationDropDown.Items = {'north', 'south', 'east', 'west', 'northeast', 'northwest', 'southeast', 'southwest', 'northoutside', 'southoutside', 'eastoutside', 'westoutside', 'northeastoutside', 'northwestoutside', 'southeastoutside', 'southwestoutside', 'best', 'bestoutside', 'layout', 'none'};
            app.LocationDropDown.ValueChangedFcn = createCallbackFcn(app, @LegendInterpreterDropDownValueChanged, true);
            app.LocationDropDown.Value = 'best';
            app.OrientationDropDownLabel = uilabel(app.GridLayout7);
            app.OrientationDropDownLabel.Text = 'Orientation';
            app.OrientationDropDown = uidropdown(app.GridLayout7);
            app.OrientationDropDown.Items = {'vertical', 'horizontal'};
            app.OrientationDropDown.ValueChangedFcn = createCallbackFcn(app, @LegendInterpreterDropDownValueChanged, true);
            app.OrientationDropDown.Value = 'vertical';
            app.NumberofcolumnsSpinnerLabel = uilabel(app.GridLayout7);
            app.NumberofcolumnsSpinnerLabel.Text = 'Number of columns';
            app.NumberofcolumnsSpinner = uispinner(app.GridLayout7);
            app.NumberofcolumnsSpinner.Limits = [1 99];
            app.NumberofcolumnsSpinner.ValueChangedFcn = createCallbackFcn(app, @LegendInterpreterDropDownValueChanged, true);
            app.NumberofcolumnsSpinner.Value = 1;
            app.LegendMarkerSizeSpinnerLabel = uilabel(app.GridLayout7);
            app.LegendMarkerSizeSpinnerLabel.Text = 'Legend MarkerSize';
            app.LegendMarkerSizeSpinner = uispinner(app.GridLayout7);
            app.LegendMarkerSizeSpinner.Limits = [1 Inf];
            app.LegendMarkerSizeSpinner.ValueChangedFcn = createCallbackFcn(app, @AaFigureFontSizeSpinnerValueChanged, true);
            app.LegendMarkerSizeSpinner.Value = 14;
            app.ExportTab = uitab(app.TabGroup);
            app.ExportTab.Title = 'Export';
            app.GridLayout3 = uigridlayout(app.ExportTab);
            app.ExportAllButton = uibutton(app.GridLayout3, 'push');
            app.ExportAllButton.ButtonPushedFcn = createCallbackFcn(app, @ExportAllButtonPushed, true);
            app.ExportAllButton.Text = 'Export All';
            app.SelectDirButton = uibutton(app.GridLayout3, 'push');
            app.SelectDirButton.ButtonPushedFcn = createCallbackFcn(app, @SelectDirButtonPushed, true);
            app.SelectDirButton.Text = 'Select Dir';
            app.ExportDirectoryEditFieldLabel = uilabel(app.GridLayout3);
            app.ExportDirectoryEditFieldLabel.Text = 'Export Directory';
            app.ExportDirectoryEditField = uieditfield(app.GridLayout3, 'text');
            app.CheckBox_png_exp = uicheckbox(app.GridLayout3);
            app.CheckBox_png_exp.Text = 'png';
            app.CheckBox_pdf_exp = uicheckbox(app.GridLayout3);
            app.CheckBox_pdf_exp.Text = 'pdf';
            app.CheckBox_pdf_exp.Value = true;
            app.CheckBox_eps_exp = uicheckbox(app.GridLayout3);
            app.CheckBox_eps_exp.Text = 'eps';
            app.CheckBox_tif_exp = uicheckbox(app.GridLayout3);
            app.CheckBox_tif_exp.Text = 'tif';
            app.ExportSelectedButton = uibutton(app.GridLayout3, 'push');
            app.ExportSelectedButton.ButtonPushedFcn = createCallbackFcn(app, @ExportSelectedButtonPushed, true);
            app.ExportSelectedButton.Text = 'Export Selected';
            app.ExportSelectionButton = uibutton(app.GridLayout3, 'push');
            app.ExportSelectionButton.ButtonPushedFcn = createCallbackFcn(app, @ExportSelectionButtonPushed, true);
            app.ExportSelectionButton.Text = 'Export Selection';
            app.CheckBox_jpg_exp = uicheckbox(app.GridLayout3);
            app.CheckBox_jpg_exp.Text = 'jpg';
            app.CheckBox_fig_exp = uicheckbox(app.GridLayout3);
            app.CheckBox_fig_exp.Text = 'fig';
            app.CustomStringLabel = uilabel(app.GridLayout3);
            app.CustomStringLabel.Text = 'Custom String';
            app.CustStrEditField = uieditfield(app.GridLayout3, 'text');
            app.Tree = uitree(app.GridLayout2);
            app.Tree.SelectionChangedFcn = createCallbackFcn(app, @TreeSelectionChanged, true);
            app.UpdateListButton = uibutton(app.GridLayout2, 'push');
            app.UpdateListButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateListButtonPushed, true);
            app.UpdateListButton.Text = 'Update List';
            app.CloseallfiguresButton = uibutton(app.GridLayout2, 'push');
            app.CloseallfiguresButton.ButtonPushedFcn = createCallbackFcn(app, @CloseallfiguresButtonPushed, true);
            app.CloseallfiguresButton.Text = 'Close all figures';
            app.FindPlotButton = uibutton(app.GridLayout2, 'push');
            app.FindPlotButton.ButtonPushedFcn = createCallbackFcn(app, @FindPlotButtonPushed, true);
            app.FindPlotButton.Text = 'Find Plot';
            app.xlinlogButton = uibutton(app.EasyPlotModuleUIFigure, 'push');
            app.xlinlogButton.ButtonPushedFcn = createCallbackFcn(app, @xlinlogButtonPushed, true);
            app.xlinlogButton.Visible = 'off';
            app.xlinlogButton.Text = 'x lin/log';
            app.xgridButton = uibutton(app.EasyPlotModuleUIFigure, 'push');
            app.xgridButton.ButtonPushedFcn = createCallbackFcn(app, @xgridButtonPushed, true);
            app.xgridButton.Visible = 'off';
            app.xgridButton.Text = 'x grid';
            app.ygridButton = uibutton(app.EasyPlotModuleUIFigure, 'push');
            app.ygridButton.ButtonPushedFcn = createCallbackFcn(app, @ygridButtonPushed, true);
            app.ygridButton.Visible = 'off';
            app.ygridButton.Text = 'y grid';
            app.ylinlogButton = uibutton(app.EasyPlotModuleUIFigure, 'push');
            app.ylinlogButton.ButtonPushedFcn = createCallbackFcn(app, @ylinlogButtonPushed, true);
            app.ylinlogButton.Visible = 'off';
            app.ylinlogButton.Text = 'y lin/log';
            % Show the figure after all components are created
            app.EasyPlotModuleUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = EasyPlotModule(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.EasyPlotModuleUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.EasyPlotModuleUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.EasyPlotModuleUIFigure)
        end
    end
end