classdef GeoCPlot < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        GeoCPlotUIFigure                matlab.ui.Figure
        NumericPanel                    matlab.ui.container.Panel
        GridLayout12                    matlab.ui.container.GridLayout
        AllnumericColumnsDropDown       matlab.ui.control.DropDown
        AllnumericColumnsDropDownLabel  matlab.ui.control.Label
        ExcludenegativeButton           matlab.ui.control.Button
        ExcludeNaNButton_2              matlab.ui.control.Button
        RangeMaxEditField               matlab.ui.control.NumericEditField
        RangeMinEditField               matlab.ui.control.NumericEditField
        ExcludeInfButton                matlab.ui.control.Button
        FilterButton                    matlab.ui.control.Button
        Panel                           matlab.ui.container.Panel
        CloseButton                     matlab.ui.control.Button
        yDropDown                       matlab.ui.control.DropDown
        yDropDownLabel                  matlab.ui.control.Label
        xDropDown                       matlab.ui.control.DropDown
        xDropDownLabel                  matlab.ui.control.Label
        Tree2                           matlab.ui.container.CheckBoxTree
        GridLayout2                     matlab.ui.container.GridLayout
        TabGroup                        matlab.ui.container.TabGroup
        StartTab                        matlab.ui.container.Tab
        GridLayout                      matlab.ui.container.GridLayout
        ExportTableButton               matlab.ui.control.Button
        DeleteSpectraButton             matlab.ui.control.Button
        DeleteColumn                    matlab.ui.control.Button
        SearchEditField                 matlab.ui.control.EditField
        SearchEditFieldLabel            matlab.ui.control.Label
        SearchButton                    matlab.ui.control.Button
        UITable                         matlab.ui.control.Table
        LoadDataButton                  matlab.ui.control.Button
        ClassificationTab               matlab.ui.container.Tab
        GridLayout4                     matlab.ui.container.GridLayout
        UnmaskButton                    matlab.ui.control.Button
        NumClassesPanel                 matlab.ui.container.Panel
        GridLayout3                     matlab.ui.container.GridLayout
        TabGroup2                       matlab.ui.container.TabGroup
        VarSelectionTab_2               matlab.ui.container.Tab
        GridLayout8                     matlab.ui.container.GridLayout
        Tree_2                          matlab.ui.container.CheckBoxTree
        ResultTab                       matlab.ui.container.Tab
        GridLayout9                     matlab.ui.container.GridLayout
        ClassTree                       matlab.ui.container.CheckBoxTree
        SelectButton_2                  matlab.ui.control.Button
        LogCheckBox                     matlab.ui.control.CheckBox
        MaskClassButton                 matlab.ui.control.Button
        sqrt_CheckBox                   matlab.ui.control.CheckBox
        SelectAllButton_2               matlab.ui.control.Button
        DeselectAllButton_2             matlab.ui.control.Button
        Add2TableButton                 matlab.ui.control.Button
        SortClassesDropDown             matlab.ui.control.DropDown
        SortClassesDropDownLabel        matlab.ui.control.Label
        ClassesSpinner                  matlab.ui.control.Spinner
        ClassesSpinnerLabel             matlab.ui.control.Label
        MaxClassesSpinner               matlab.ui.control.Spinner
        MaxClassesSpinnerLabel          matlab.ui.control.Label
        MinClassesSpinner               matlab.ui.control.Spinner
        MinClassesSpinnerLabel          matlab.ui.control.Label
        CLR_CheckBox                    matlab.ui.control.CheckBox
        Norm_CheckBox                   matlab.ui.control.CheckBox
        RunClassTestButton              matlab.ui.control.Button
        IterationsSpinnerLabel          matlab.ui.control.Label
        IterationsSpinner               matlab.ui.control.Spinner
        RandomSeedSpinnerLabel          matlab.ui.control.Label
        RandomSeedSpinner               matlab.ui.control.Spinner
        UIAxes                          matlab.ui.control.UIAxes
        UIAxes2_2                       matlab.ui.control.UIAxes
        SelectAllButton_3               matlab.ui.control.Button
        ResetMaskButton                 matlab.ui.control.Button
        MaskSelectionButton             matlab.ui.control.Button
        VariableDropDown                matlab.ui.control.DropDown
        MethodDropDown                  matlab.ui.control.DropDown
        MethodDropDownLabel             matlab.ui.control.Label
        ClassTable                      matlab.ui.control.Table
        VarSelectionTab                 matlab.ui.container.Tab
        GridLayout6                     matlab.ui.container.GridLayout
        SelectButton                    matlab.ui.control.Button
        DeselectAllButton               matlab.ui.control.Button
        SelectAllButton                 matlab.ui.control.Button
        VarSelectionTree                matlab.ui.container.CheckBoxTree
        VarSelectionHistPlot            matlab.ui.control.UIAxes
        DataSelectionTab                matlab.ui.container.Tab
        GridLayout5                     matlab.ui.container.GridLayout
        PlotsqrtCheckBox                matlab.ui.control.CheckBox
        MarkerSizeEditField             matlab.ui.control.NumericEditField
        InvertColormapCheckBox          matlab.ui.control.CheckBox
        StoreClassMedianButton          matlab.ui.control.Button
        StoreClassMeanButton            matlab.ui.control.Button
        StoreDataButton                 matlab.ui.control.Button
        ColormapPreviewCheckBox         matlab.ui.control.CheckBox
        Options_ColormapDropDown        matlab.ui.control.DropDown
        ColormapDropDownLabel           matlab.ui.control.Label
        CorPlotButton                   matlab.ui.control.Button
        cmap_table                      matlab.ui.control.Table
        PlotCLRCheckBox                 matlab.ui.control.CheckBox
        PlotLogCheckBox                 matlab.ui.control.CheckBox
        PlotDataCheckBox                matlab.ui.control.CheckBox
        SymbolDropDown                  matlab.ui.control.DropDown
        SymbolDropDownLabel             matlab.ui.control.Label
        PlotsTab                        matlab.ui.container.Tab
        GridLayout7                     matlab.ui.container.GridLayout
        TabGroup3                       matlab.ui.container.TabGroup
        ScatterPlotTab                  matlab.ui.container.Tab
        GridLayout10                    matlab.ui.container.GridLayout
        GapDropDown                     matlab.ui.control.DropDown
        GapDropDownLabel                matlab.ui.control.Label
        PlotDataCheckBox_2              matlab.ui.control.CheckBox
        PlotLogCheckBox_2               matlab.ui.control.CheckBox
        PlotCLRCheckBox_2               matlab.ui.control.CheckBox
        LinkAxesCheckBox                matlab.ui.control.CheckBox
        ExcludeEmptyCheckBox            matlab.ui.control.CheckBox
        PlotsqrtCheckBox_2              matlab.ui.control.CheckBox
        NewCorrelationPlotButton        matlab.ui.control.Button
        TernPlotTab                     matlab.ui.container.Tab
        GridLayout11                    matlab.ui.container.GridLayout
        Norm_CheckBox_2                 matlab.ui.control.CheckBox
        PlotCLRCheckBox_3               matlab.ui.control.CheckBox
        PlotsqrtCheckBox_3              matlab.ui.control.CheckBox
        MaxSubpltSpinner                matlab.ui.control.Spinner
        MaxSubpltSpinnerLabel           matlab.ui.control.Label
        PlotDataCheckBox_3              matlab.ui.control.CheckBox
        PlotLogCheckBox_3               matlab.ui.control.CheckBox
        NewTernPlotButton               matlab.ui.control.Button
        ColorPreviewCheckBox            matlab.ui.control.CheckBox
        ActiveSetSpinner                matlab.ui.control.Spinner
        ActiveSetSpinnerLabel           matlab.ui.control.Label
        movedownButton                  matlab.ui.control.Button
        moveupButton                    matlab.ui.control.Button
        ClearAllButton                  matlab.ui.control.Button
        RemoveGroupButton               matlab.ui.control.Button
        Tree_MultiCor_Groups            matlab.ui.container.CheckBoxTree
        Tree_MultiCor_VarName           matlab.ui.container.CheckBoxTree
        MultiCor_table                  matlab.ui.control.Table
    end


    properties (Access = private)
        Data % Description
        click_time % Description
        new_set=true
        mainapp_data
        ColorMaps
    end

    methods (Access = private)
        function ResetTree(app,Tree2reset)
            a = Tree2reset.Children;
            a.delete;
        end

        function update_data(app)
            %              d = uiprogressdlg(app.GeoCPlotUIFigure,'Title','Please Wait...',...
            %                 'Message','Updating Data...','Cancelable','off');
            %             drawnow
            % update Table
            app.UITable.Data=app.Data.Active.DataMat;
            if not(isempty(app.UITable.Data))

            end
            app.Data.Active.VariableNames=app.Data.Active.DataMat.Properties.VariableNames;

            app.UITable.ColumnName=app.Data.Active.VariableNames;

            % save checked nodes
            CheckedNodesV=app.VarSelectionTree.CheckedNodes;
            CheckedNodesT=app.Tree_2.CheckedNodes;
            if not(isempty(CheckedNodesV))
                CheckedNodes_V2= {CheckedNodesV.Text};
            end
            if not(isempty(CheckedNodesT))
                CheckedNodes_T2= {CheckedNodesT.Text};
            end

            %update trees
            ResetTree(app,app.VarSelectionTree)
            ResetTree(app,app.Tree_2)
            test=any(cellfun(@isnumeric,table2cell(app.UITable.Data)));
            for n=1:numel(app.Data.Active.VariableNames)
                if test(n)==true
                    if iscell(app.Data.Active.DataMat(:,n)) && isnumeric(app.Data.Active.DataMat{:,n})
                        uitreenode(app.VarSelectionTree,"Text",app.Data.Active.VariableNames{n},'NodeData',[app.Data.Active.DataMat{:,n}]);
                        uitreenode(app.Tree_2,"Text",app.Data.Active.VariableNames{n},'NodeData',[app.Data.Active.DataMat{:,n}]);
                    else
                        uitreenode(app.VarSelectionTree,"Text",app.Data.Active.VariableNames{n},'NodeData',app.Data.Active.DataMat(:,n));
                        uitreenode(app.Tree_2,"Text",app.Data.Active.VariableNames{n},'NodeData',app.Data.Active.DataMat(:,n));
                    end
                end
            end
            % restore checked nodes
            if not(isempty(CheckedNodesT))
                CheckedNodes_all= {app.Tree_2.Children.Text};
                app.Tree_2.CheckedNodes=app.Tree_2.Children((ismember(CheckedNodes_all,CheckedNodes_T2)));
            end
            if not(isempty(CheckedNodesV))
                CheckedNodes_all= {app.VarSelectionTree.Children.Text};
                app.VarSelectionTree.CheckedNodes=app.VarSelectionTree.Children((ismember(CheckedNodes_all,CheckedNodes_V2)));
            end

            Tree_2SelectionChanged(app)
            VarSelectionTreeSelectionChanged(app)
            %    delete(d)
        end


        function cmap= update_cmap(app,num_C)

            switch app.Options_ColormapDropDown.Value
                case 'parula'
                    cmap=parula(num_C);
                case 'turbo'
                    cmap=turbo(num_C);
                case 'hsv'
                    cmap=hsv(num_C);
                case 'hot'
                    cmap=hot(num_C);
                case 'cool'
                    cmap=cool(num_C);
                case 'spring'
                    cmap=spring(num_C);
                case 'summer'
                    cmap=summer(num_C);
                case 'autumn'
                    cmap=autumn(num_C);
                case 'winter'
                    cmap=winter(num_C);
                case 'gray'
                    cmap=gray(num_C);
                case 'bone'
                    cmap=bone(num_C);
                case 'copper'
                    cmap=copper(num_C);
                case 'pink'
                    cmap=pink(num_C);
                case 'jet'
                    cmap=jet(num_C);
                case 'lines'
                    cmap=lines(num_C);
                case 'colorcube'
                    cmap=colorcube(num_C);
                case 'prism'
                    cmap=prism(num_C);
                case 'flag'
                    cmap=flag(num_C);
                case 'white'
                    cmap=white(num_C);
                case 'ABC'
                    if num_C==4
                        cmap=[
                            0.65098      0.65098      0.65098
                            1      0.41176      0.16078
                            0.39216      0.83137      0.07451
                            0.07451      0.62353            1];
                    elseif  num_C==3
                        cmap=[
                            1      0.41176      0.16078
                            0.39216      0.83137      0.07451
                            0.07451      0.62353            1];
                    end

                case 'custom'
                    if size(app.cmap_table.Data.rgb,1)==num_C
                        cmap=app.cmap_table.Data.rgb;
                    else
                        cmap=white(num_C).*0;
                    end
            end
        end

        function update_cmap_table(app)

            value = app.MethodDropDown.Value;
            switch value
                case 'Unique String'
                    try
                        className=unique(app.UITable.Data.(char(app.VariableDropDown.Value)));
                    catch
                        thx=app.UITable.Data.(char(app.VariableDropDown.Value));
                        thx(cellfun(@isempty,thx))={''};

                        idx=find(cellfun(@isempty,thx));

                        app.UITable.Data(idx,char(app.VariableDropDown.Value))={' '};
                        app.UITable.Data(idx,char(app.VariableDropDown.Value))={''};
                        className=unique(app.UITable.Data.(char(app.VariableDropDown.Value)));
                        disp('fixed empty numeric cell2 ''''')
                    end
                    className=className(app.ClassTable.Data.Active);
                    Value=[1:numel(className)]';
                    Active=true(numel(Value),1);

                    app.ClassTable.Visible=true;

                    rgb= PlotMap_UpdateColormap(app,sum(app.ClassTable.Data.Active));

                    where=          app.UITable.Data.(char(app.VariableDropDown.Value));
                    classNumber=nan(size(where));
                    c=0;
                    for n=1:numel(className)
                        classNumber(ismember(where,className(n)))=  n;
                    end
                    nvalues=[];
                    for n=1:numel(unique(classNumber(isfinite(classNumber))))
                        nvalues(end+1)=sum(classNumber(isfinite(classNumber)&(app.UITable.Data.Active))==n);%#ok
                    end


                case 'Numeric'
                    className=string(unique(app.UITable.Data.(char(app.VariableDropDown.Value))));
                    className=className(app.ClassTable.Data.Active);

                    Value=unique(app.UITable.Data.(char(app.VariableDropDown.Value)));
                    if any(isnan(Value))

                    end
                    Value=Value(app.ClassTable.Data.Active);

                    Active=true(numel(Value),1);

                    app.ClassTable.Visible=true;

                    rgb= PlotMap_UpdateColormap(app,sum(app.ClassTable.Data.Active));
                    where= app.UITable.Data.(char(app.VariableDropDown.Value));

                    if iscell(where)
                        where=[where{:}]';
                    end

                    classNumber=nan(size(where));
                    for n=1:numel(Value)
                        classNumber(ismember(where,Value(n)))=  n;
                    end

                    nvalues=[];
                    for n=1:numel(unique(classNumber(isfinite(classNumber))))
                        nvalues(end+1)=sum(classNumber(isfinite(classNumber)&(app.UITable.Data.Active))==n);%#ok
                    end


                case 'NumClasses'
                    app.NumClassesPanel.Visible=true;
                    app.VariableDropDown.Visible=false;
                    app.ClassTable.Visible=false;


                    try
                        mapnr=1;
                        ClassMapNr=find(app.Data.Classes(mapnr).n_classes==app.ClassesSpinner.Value);
                        if isstruct(app.Data.Classes) && isempty(ClassMapNr)
                            app.MaxClassesSpinner.Value=app.ClassesSpinner.Value;
                            app.MinClassesSpinner.Value=2;
                            RunClassTestButtonPushed(app)
                            ClassMapNr=find(app.Data.Classes(mapnr).n_classes==app.ClassesSpinner.Value);
                        end
                        classNumber=app.Data.Classes(mapnr).ClassMap(ClassMapNr).classNumber;
                        className=app.Data.Classes(mapnr).ClassMap(ClassMapNr).ClassesNames;
                    catch
                        if not(isfield(app.Data,'Classes'))
                            return
                        end
                        app.MaxClassesSpinner.Value=app.ClassesSpinner.Value;
                        app.MinClassesSpinner.Value=2;
                        RunClassTestButtonPushed(app)
                        ClassMapNr=find(app.Data.Classes(mapnr).n_classes==app.ClassesSpinner.Value);
                        classNumber=app.Data.Classes(mapnr).ClassMap(ClassMapNr).classNumber;
                        className=app.Data.Classes(mapnr).ClassMap(ClassMapNr).ClassesNames;
                    end

                    Active=true(numel(unique(classNumber(not(isnan(classNumber))))),1);

                    Value=sort(unique(classNumber(not(isnan(classNumber)))));


                    rgb= PlotMap_UpdateColormap(app,numel(Active));


                    nvalues=[];
                    for n=1:numel(unique(classNumber(isfinite(classNumber))))%ath here mask
                        nvalues(end+1)=sum(classNumber(isfinite(classNumber)&(app.UITable.Data.Active))==n);%#ok
                    end
            end


            T=table(Active,className,Value,rgb,nvalues');
            T.Properties.VariableNames={'Active','Name','Value','rgb','n'};


            %T=table(Active,className,Value,cmap);
            %T.Properties.VariableNames={'Active','Name','Marker','SymbolSize','rgb'};

            %  switch markerdropdown
            % case 'custom'
            %   if size(app.cmap_table.Data.Symbol,1)==num_C
            %                           Marker=app.cmap_table.Data.Marker;
            %                           SymbolSize=app.cmap_table.Data.SymbolSize;
            %   else
            %Marker=markerdropdown;
            %SymbolSize=SymbolSize_number;
            %   end
            % otherwise
            %Marker=markerdropdown;
            %SymbolSize=SymbolSize_number;


            app.cmap_table.Data=T;
            if app.ColormapPreviewCheckBox.Value==true
                for n=1:numel(Active)
                    s = uistyle('BackgroundColor',rgb(n,:));
                    addStyle(app.cmap_table,s,'row',n)

                end
                s = uistyle('BackgroundColor',[1 1 1 ]);
                addStyle(app.cmap_table,s,'column',[1:3 5])
            else
                s = uistyle('BackgroundColor',[1 1 1 ]);

                addStyle(app.cmap_table,s,'column',1:5)
            end
        end


        function update_class_table(app)

            value = app.MethodDropDown.Value;
            switch value
                case 'Unique String'
                    try
                        className=unique(app.UITable.Data.(char(app.VariableDropDown.Value)));
                    catch
                        thx=app.UITable.Data.(char(app.VariableDropDown.Value));
                        [thx(cellfun(@isempty,app.UITable.Data.(char(app.VariableDropDown.Value))))]={' '};
                        app.UITable.Data.(char(app.VariableDropDown.Value))=thx;
                        className=unique(app.UITable.Data.(char(app.VariableDropDown.Value)));

                    end

                    Value=[1:numel(className)]';
                    try
                        if isequal(className,app.ClassTable.Data.Name)
                            Active= app.ClassTable.Data.Active;
                        else
                            Active=true(numel(unique(app.UITable.Data.(char(app.VariableDropDown.Value)))),1);
                        end
                    catch
                        Active=true(numel(unique(app.UITable.Data.(char(app.VariableDropDown.Value)))),1);
                    end
                    app.ClassTable.Visible=true;

                    where= string(app.UITable.Data.(char(app.VariableDropDown.Value)));
                    nvalues=zeros(size(Value));

                    for n=1:numel(className)

                        nvalues(n)= sum(ismember(where(find(app.UITable.Data.Active)),className(n)));
                    end

                case 'Numeric'
                    className=string(unique(app.UITable.Data.(char(app.VariableDropDown.Value))));
                    Value=unique(app.UITable.Data.(char(app.VariableDropDown.Value)));

                    if any(isnan(Value))
                        className(isnan(Value))=[];
                        Value(isnan(Value))=[];
                        disp('NaN values ignored')
                    end

                    Active=true(size(Value));

                    app.ClassTable.Visible=true;

                    nvalues=zeros(size(Value));
                    where= app.UITable.Data.(char(app.VariableDropDown.Value));
                    for n=1:numel(Value)
                        nvalues(n)= sum(ismember(where(app.UITable.Data.Active),Value(n)));
                    end


                case 'NumClasses'
                    app.NumClassesPanel.Visible=true;
                    app.VariableDropDown.Visible=false;
                    app.ClassTable.Visible=false;


                    try
                        mapnr=1;
                        ClassMapNr=find(app.Data.Classes(mapnr).n_classes==app.ClassesSpinner.Value);
                        if isempty(ClassMapNr)
                            app.MaxClassesSpinner.Value=app.ClassesSpinner.Value;
                            app.MinClassesSpinner.Value=2;
                            RunClassTestButtonPushed(app)
                            ClassMapNr=find(app.Data.Classes(mapnr).n_classes==app.ClassesSpinner.Value);
                        end
                        classNumber=app.Data.Classes(mapnr).ClassMap(ClassMapNr).classNumber;
                        className=app.Data.Classes(mapnr).ClassMap(ClassMapNr).ClassesNames;
                    catch
                        if not(isfield(app.Data,'Classes'))
                            return
                        end
                        app.MaxClassesSpinner.Value=app.ClassesSpinner.Value;
                        app.MinClassesSpinner.Value=2;
                        RunClassTestButtonPushed(app)
                        ClassMapNr=find(app.Data.Classes(mapnr).n_classes==app.ClassesSpinner.Value);
                        classNumber=app.Data.Classes(mapnr).ClassMap(ClassMapNr).classNumber;
                        className=app.Data.Classes(mapnr).ClassMap(ClassMapNr).ClassesNames;
                    end


                    Value=sort(unique(classNumber(not(isnan(classNumber)))));
                    nvalues=zeros(size(Value));

                    if not(isempty(app.ClassTable.Data))&& isequal(className,app.ClassTable.Data.Name)
                        Active= app.ClassTable.Data.Active;
                    else
                        % Active=true(numel(unique(app.UITable.Data.(char(app.VariableDropDown.Value)))),1);
                        Active=true(size(Value));

                    end


                    for n=1:numel(unique(classNumber(isfinite(classNumber))))
                        nvalues(n)=sum(classNumber(isfinite(classNumber))==n);
                    end

            end


            T=table(Active,className,Value,nvalues);
            T.Properties.VariableNames={'Active','Name','Value','n'};

            app.ClassTable.Data=T;
            app.ClassTable.ColumnName=T.Properties.VariableNames;

        end

        function add_cbar(app,hFig,cmap,title,maxval)

            ax = axes('Parent',hFig,...
                'Position',[0.529761904761905 0.53889 0.419973544973545 0.37317313416009]);
            values=app.cmap_table.Data.Value(app.cmap_table.Data.Active);
            Y=1:numel(values);
            X=ones(size(Y));


            cb=colorbar;
            cb.YTick = 0.5 : 1 : numel(unique(values(not(isnan(values)))));

            colormap(cmap)
            if exist('maxval','var')
                caxis([0, maxval])
            else
                caxis([0, numel(unique(values(not(isnan(values)))))])
            end
            %cb.YTick = 0.5 : 1 :  numel(unique(values,omit));

            cb.TickLabels = app.cmap_table.Data.Name(app.cmap_table.Data.Active) ;
            set(cb,'TickLabelInterpreter','none')
            cb.FontSize = 8;
            %                 legend( app.cmap_table.Data.Name )

            ts=sgtitle(title);
            set(ts, 'Interpreter', 'none')
            ax.Visible=false;


        end
        function reset_app(app)
            app.Data=[];
            app.Data.MultiCorPlot=struct();
            app.ActiveSetSpinner.Value=0;
            app.ClassTable.Data=[];
            app.cmap_table.Data=[];
            app.UITable.Data=[];
            ResetTree(app,app.VarSelectionTree)
            ResetTree(app,app.Tree_2)
            app.MethodDropDown.Value='Unique String';
            MethodDropDownValueChanged(app)
        end


        function update_MultiCor_table(app,selected_MultiCor)
            checkedNodes=app.Tree_MultiCor_Groups.CheckedNodes;

            if not(isempty(checkedNodes))
                checkedNodes= app.Tree_MultiCor_Groups.CheckedNodes(cellfun(@isnumeric,{app.Tree_MultiCor_Groups.CheckedNodes.NodeData}));
                nd=reshape([checkedNodes.NodeData],2,[])';
                nodes={app.Tree_MultiCor_Groups.Children(:).Children};
                nodes=cat(1,nodes{:});
                nd_all=reshape([nodes.NodeData],2,[])';
                %
                %                 for n=1:size(unique(nd_all(:,1)))
                test= ismember(nd_all,nd,'rows');
            end

            T=table();
            if isstruct(app.Data.MultiCorPlot) && not(isempty(app.Data.MultiCorPlot)) && not(isempty(fieldnames(app.Data.MultiCorPlot)))
                app.ActiveSetSpinner.Visible=true;
                app.ActiveSetSpinnerLabel.Visible=true;
                if numel(app.Data.MultiCorPlot)<selected_MultiCor
                    selected_MultiCor=numel(app.Data.MultiCorPlot);
                    app.ActiveSetSpinner.Value=selected_MultiCor;
                    disp('Error selected_MultiCor outside of numel(app.Data.MultiCorPlot)')
                end
                for selected_subMultiCor=1:numel(app.Data.MultiCorPlot(selected_MultiCor).ClassNames)
                    rgb=app.Data.MultiCorPlot(selected_MultiCor).cmap{selected_subMultiCor};
                    Symbol=app.Data.MultiCorPlot(selected_MultiCor).Symbol{selected_subMultiCor};
                    Name=app.Data.MultiCorPlot(selected_MultiCor).ClassNames{selected_subMultiCor};
                    SymbolSize=app.Data.MultiCorPlot(selected_MultiCor).SymbolSize{selected_subMultiCor};
                    Active= app.Data.MultiCorPlot(selected_MultiCor).Active{selected_subMultiCor};
                    Group=repmat(['Group_' num2str(selected_subMultiCor)],size(Active,1),1);
                    Group=string(Group);
                    classNumber=app.Data.MultiCorPlot(selected_MultiCor).classNumber{selected_subMultiCor};
                    nvalues=[];
                    for n=1:numel(unique(classNumber(isfinite(classNumber))))
                        test4nan=sum(isnan(app.Data.MultiCorPlot(selected_MultiCor).DataMat{selected_subMultiCor}),2); %ath here change to mask and store mask insead of nan
                        test4nan=test4nan(isfinite(classNumber))==size(app.Data.MultiCorPlot(selected_MultiCor).DataMat{selected_subMultiCor},2);
                        nvalues(end+1)=sum(classNumber(isfinite(classNumber)& not(test4nan))==n);%#ok
                    end
                    try
                        T=[T;table(Active,Group,Name,Symbol,SymbolSize,rgb,nvalues')];%here
                    catch ME
                        disp('Error in MultiCor_table update')
                        ME
                    end

                end


                T.Properties.VariableNames={'Active';'Group'; 'Name'; 'Symbol'; 'SymbolSize';'rgb';'n'};


            else
                app.ActiveSetSpinner.Visible=false;
                app.ActiveSetSpinnerLabel.Visible=false;
                app.ActiveSetSpinner.Value=0;
            end


            % T=table(Active,group,className,Symbol',SymbolSize,cmap);
            if exist('test','var') && not(isempty(T))
                T=T(test,:);

            end

            if app.ExcludeEmptyCheckBox.Value==true && not(isempty(T))
                T=T(not(T.(size(T,2)))==0,:);
            end
            app.MultiCor_table.Data=T;

            if app.ColorPreviewCheckBox.Value==true&& not(isempty(T))
                for n=1:numel(app.MultiCor_table.Data.Active)
                    s = uistyle('BackgroundColor',app.MultiCor_table.Data.rgb(n,:));
                    addStyle(app.MultiCor_table,s,'row',n)

                end
                s = uistyle('BackgroundColor',[1 1 1 ]);
                addStyle(app.MultiCor_table,s,'column',[1:5 7])
            else
                s = uistyle('BackgroundColor',[1 1 1 ]);

                addStyle(app.MultiCor_table,s,'column',1:7)
            end


        end


        function update_MultiCor_trees(app,whichtree)
            switch whichtree
                case 'Varname'
                    ResetTree(app,app.Tree_MultiCor_VarName)
                    if isstruct(app.Data.MultiCorPlot)&& not(isempty(app.Data.MultiCorPlot)) && not(isempty(fieldnames(app.Data.MultiCorPlot)))
                        for n=1:numel(app.Data.MultiCorPlot)
                            p=  uitreenode(app.Tree_MultiCor_VarName,"Text",['Set_' num2str(n)],'NodeData',app.Data.MultiCorPlot(n));
                            for m=1:numel(app.Data.MultiCorPlot(n).VarNames)
                                uitreenode(p,"Text",app.Data.MultiCorPlot(n).VarNames{m},'NodeData',[n m]);
                            end

                        end
                    end
                case 'Groups'
                    selected_MultiCor=app.ActiveSetSpinner.Value;
                    CheckedNodes=app.Tree_MultiCor_Groups.CheckedNodes;
                    if not(isempty(CheckedNodes))
                        CheckedNodes= app.Tree_MultiCor_Groups.CheckedNodes(cellfun(@isnumeric,{app.Tree_MultiCor_Groups.CheckedNodes.NodeData}));

                        nodes={app.Tree_MultiCor_Groups.Children(:).Children};
                        nodes=cat(1,nodes{:});
                        nd_all=reshape([nodes.NodeData],2,[])';
                        nd_CheckedNodes=reshape([CheckedNodes.NodeData],2,[])';
                    end
                    ResetTree(app,app.Tree_MultiCor_Groups)

                    if isfield(app.Data,'MultiCorPlot') && isstruct(app.Data.MultiCorPlot)  && not(isempty(app.Data.MultiCorPlot)) && not(isempty(fieldnames(app.Data.MultiCorPlot)))

                        for n=1:numel(app.Data.MultiCorPlot(selected_MultiCor).ClassNames)
                            p=  uitreenode(app.Tree_MultiCor_Groups,"Text",['Group_' num2str(n)],'NodeData',app.Data.MultiCorPlot(selected_MultiCor));
                            for m=1:numel(app.Data.MultiCorPlot(selected_MultiCor).ClassNames{n})
                                uitreenode(p,"Text",app.Data.MultiCorPlot(selected_MultiCor).ClassNames{n}{m},'NodeData',[n m]);
                            end
                        end


                        nodes={app.Tree_MultiCor_Groups.Children(:).Children};
                        nodes=cat(1,nodes{:});
                        nd_all_new=reshape([nodes.NodeData],2,[])';

                        if not(isempty(CheckedNodes))

                            if isequal(nd_all,nd_all_new)
                                groups=unique(nd_CheckedNodes(:,1));
                                nodes2check=[];
                                for n=1:numel(groups)
                                    group=groups(n);
                                    nodes2check=[nodes2check; app.Tree_MultiCor_Groups.Children(group).Children(nd_CheckedNodes(nd_CheckedNodes(:,1)==group,2))];
                                end
                                app.Tree_MultiCor_Groups.CheckedNodes=nodes2check;

                            end
                        end
                    end
                case 0
                    update_MultiCor_trees(app,'Varname')
                    update_MultiCor_trees(app,'Groups')
            end
        end

        function T = fixTcells(app,T)
            nums_in_cells=sum(cellfun(@isnumeric,table2cell(T)))==height(T);
            %T=T(not(cellfun(@isempty,T.Id)),:);
            for i = 1:width(T)
                if iscell(T.(i)) && nums_in_cells(i)==true
                    thx=T.(i);
                    thx(cellfun(@isempty, T.(i)))={nan};
                    T.(i) = [thx{:}]';
                end
            end
        end

        function [ColorMap] = PlotMap_UpdateColormap(app,Resolution,~)
            if (isequal(app.Options_ColormapDropDown.Value,123)||isequal(app.Options_ColormapDropDown.Value,[123 123 123])) && (Resolution==4||Resolution==3||Resolution==6)
                if Resolution==4
                    ColorMap=[
                        0.65098      0.65098      0.65098
                        1      0.41176      0.16078
                        0.39216      0.83137      0.07451
                        0.07451      0.62353            1];
                elseif  Resolution==3
                    ColorMap=[
                        1      0.41176      0.16078
                        0.39216      0.83137      0.07451
                        0.07451      0.62353            1];
                elseif  Resolution==6
                    ColorMap=[0.9020	0.9020	0.9020
                        0.9294	0.6941	0.1255
                        0.7176	0.2745	1.0000
                        0.8510	0.3255	0.0980
                        0.3922	0.8314	0.0745
                        0.0745	0.6235	1.0000];
                end


            elseif  isfield(app.Data,'CustomColorMap') && isequal(app.Options_ColormapDropDown.Value,app.Data.CustomColorMap) && size(app.Data.CustomColorMap,1)==Resolution
                ColorMap=app.Data.CustomColorMap;
            else
                %  SelColorMap = find(cellfun(@(x) isequal(x, app.Options_ColormapDropDown.Value), app.Options_ColormapDropDown.ItemsData))

                if app.InvertColormapCheckBox.Value
                    ColorData =app.Options_ColormapDropDown.Value;
                else
                    ColorData =flip(app.Options_ColormapDropDown.Value);
                end

                if Resolution==1
                    ColorMap = [0,0,0];
                else
                    Step = (Resolution-1)/(size(ColorData,1)-1);
                    X = 1:Step:Resolution;


                    ColorMap = zeros(length(1:Resolution),size(ColorData,2));
                    for i = 1:size(ColorData,2)
                        ColorMap(:,i) = interp1(X',ColorData(:,i),1:Resolution);
                    end

                    % upper & lower
                end
            end
        end

        function update_class_tree(app)
            %reset tree
            a = app.ClassTree.Children;
            a.delete;

            if  not(isempty(app.Data))
                %build tree
                for i=1:numel(app.Data.ClassesNames)

                    p = uitreenode(app.ClassTree,'Text',string(app.Data.ClassesNames{i}),'NodeData',[i,0]);
                    %            expand(p)

                    for j=1:numel(app.Data.Classes(i).ClassMapNames)

                        p1 = uitreenode(p,'Text',string(app.Data.Classes(i).ClassMapNames{j}),'NodeData',[i,j]);
                        %       expand(p1)

                        for k=1:numel(app.Data.Classes(i).ClassMap(j).ClassesNames)
                            p2=uitreenode(p1,'Text',string(app.Data.Classes(i).ClassMap(j).ClassesNames{k}),'NodeData',[i,j,k]); %# ok
                        end
                    end
                    app.ClassTree.Visible=true;
                end

            else
                %       app.ClassTree.Visible=false;

            end
        end

%         function cmap_struct=ReadColorMaps(app)
%            
% 
%             if isdeployed
%                 XMap_ColorMaps_path=fullfile(app.MinPlot.installpath,'Dev','XMT','XMap_ColorMaps.txt');
%             else
%                 XMap_ColorMaps_path=which(fullfile('Dev','XMT','XMap_ColorMaps.txt'));
%             end
% 
% 
%             fid = fopen(XMap_ColorMaps_path,'r');
% 
%             Compt = 0;
% 
%             cmap_struct(1).Name = 'None';
%             cmap_struct(1).Code = 0;
% 
%             Compt = 0;
%             ErrorLoad = 0;
%             while 1
%                 tline = fgetl(fid);
% 
%                 if isequal(tline,-1)
%                     break
%                 end
% 
%                 if length(tline) >= 1
%                     if isequal(tline(1),'>')
%                         Compt = Compt+1;
%                         cmap_struct(Compt).Name = tline(3:end);
%                         Row = 0;
%                         while 1
%                             tline = fgetl(fid);
%                             if isequal(tline,-1) || isequal(tline,'')
%                                 break
%                             end
%                             NUM = strread(tline,'%f');
%                             Row = Row+1;
%                             cmap_struct(Compt).Code(Row,1:3) = NUM(1:3);
%                         end
% 
%                     end
%                 end
%             end
%             fclose(fid);
% 
% 
%             cmap_struct(end+1).Name= 'parula (MATLAB)';
%             cmap_struct(end).Code(:,1:3)=parula(256);
%             cmap_struct(end+1).Name= 'turbo (MATLAB)';
%             cmap_struct(end).Code(:,1:3)=turbo(256);
%             cmap_struct(end+1).Name= 'hsv (MATLAB)';
%             cmap_struct(end).Code(:,1:3)=hsv(256);
%             cmap_struct(end+1).Name= 'hot (MATLAB)';
%             cmap_struct(end).Code(:,1:3)=hot(256);
%             cmap_struct(end+1).Name= 'cool (MATLAB)';
%             cmap_struct(end).Code(:,1:3)=cool(256);
%             cmap_struct(end+1).Name= 'spring (MATLAB)';
%             cmap_struct(end).Code(:,1:3)=spring(256);
%             cmap_struct(end+1).Name= 'summer (MATLAB)';
%             cmap_struct(end).Code(:,1:3)=summer(256);
%             cmap_struct(end+1).Name= 'autumn (MATLAB)';
%             cmap_struct(end).Code(:,1:3)=autumn(256);
%             cmap_struct(end+1).Name= 'winter (MATLAB)';
%             cmap_struct(end).Code(:,1:3)=winter(256);
%             cmap_struct(end+1).Name= 'gray (MATLAB)';
%             cmap_struct(end).Code(:,1:3)=gray(256);
%             cmap_struct(end+1).Name= 'bone (MATLAB)';
%             cmap_struct(end).Code(:,1:3)=bone(256);
%             cmap_struct(end+1).Name= 'copper (MATLAB)';
%             cmap_struct(end).Code(:,1:3)=copper(256);
%             cmap_struct(end+1).Name= 'pink (MATLAB)';
%             cmap_struct(end).Code(:,1:3)=pink(256);
%             cmap_struct(end+1).Name= 'jet (MATLAB)';
%             cmap_struct(end).Code(:,1:3)=jet(256);
%             cmap_struct(end+1).Name= 'lines (MATLAB)';
%             cmap_struct(end).Code(:,1:3)=lines(256);
%             cmap_struct(end+1).Name= 'colorcube (MATLAB)';
%             cmap_struct(end).Code(:,1:3)=colorcube(256);
%             cmap_struct(end+1).Name= 'prism (MATLAB)';
%             cmap_struct(end).Code(:,1:3)=prism(256);
%             cmap_struct(end+1).Name= 'flag (MATLAB)';
%             cmap_struct(end).Code(:,1:3)=flag(256);
%             cmap_struct(end+1).Name= 'white (MATLAB)';
%             cmap_struct(end).Code(:,1:3)=white(256);
%             cmap_struct(end+1).Name= 'ABC';
%             cmap_struct(end).Code(:,1:3)=123;
%             cmap_struct(end+1).Name= 'custom';
%             cmap_struct(end).Code(:,1:3)=white(256).*0;
% 
%         end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, T, mainapp)

            if exist('mainapp','Var')
                app.mainapp_data=mainapp;
            end

            if exist('T','Var') && istable(T) && not(isempty(T))
                T = fixTcells(app,T);
                app.LoadDataButton.Visible=false;
                app.Data.Raw.T=T;

                if any(strcmp('Active',app.Data.Raw.T.Properties.VariableNames))
                    app.Data.Active.VariableNames=app.Data.Raw.T.Properties.VariableNames;
                    app.Data.Active.DataMat=app.Data.Raw.T;
                else
                    A=table();
                    A.Active=true(size(app.Data.Raw.T,1),1);
                    app.Data.Active.DataMat=[A app.Data.Raw.T];
                    app.Data.Active.VariableNames=app.Data.Active.DataMat.Properties.VariableNames;
                end

                update_data(app)
               % update_data(app)
            end
            app.Data.MultiCorPlot=struct();
            app.ActiveSetSpinner.Value=0;


            %% ColormapDropDown
            %    cmap_struct=ReadColorMaps(app);
            if isdeployed && exist('mainapp','Var') && isprop(app.mainapp_data.cmap_file_path)
                cmap_struct=ReadColorMaps(app.mainapp_data.cmap_file_path);
            elseif isdeployed && exist('mainapp','Var')
                cmap_struct=ReadColorMaps(fullfile(app.mainapp_data.installpath,'Dev','XMT','XMap_ColorMaps.txt'));
            else
                cmap_struct=ReadColorMaps(which('XMap_ColorMaps.txt'));
            end

            app.Options_ColormapDropDown.Items={cmap_struct.Name};
            app.Options_ColormapDropDown.ItemsData={cmap_struct.Code};
            MethodDropDownValueChanged(app)

        end

        % Button pushed function: LoadDataButton
        function LoadDataButtonPushed(app, event)
            try
                f = figure('Renderer', 'painters', 'Position', [-100 -100 0 0]);

                [file,path]=uigetfile({'*.csv;*.xls;*.txt;*.xlsx'},"MultiSelect","off",'Select Data');
                delete(f)
                if isequal(file,0)
                    return
                end


                reset_app(app)
            catch
                message='Error not a valid file';
                uialert(app.GeoCPlotUIFigure,message,'Error');
            end
            app.Data.Raw.T=readtable(fullfile(path,file));


            app.Data.Active.VariableNames=app.Data.Raw.T.Properties.VariableNames;
            A=table();
            A.Active=true(size(app.Data.Raw.T,1),1);

            app.Data.Active.DataMat=[A app.Data.Raw.T];

            update_data(app)


        end

        % Button pushed function: CorPlotButton
        function CorPlotButtonPushed(app, event)
            if isempty(app.Data)
                return
            end
            if not(isempty(app.VarSelectionTree.CheckedNodes))
                VarNames={app.VarSelectionTree.CheckedNodes.Text};
                DataIn=table2array([app.VarSelectionTree.CheckedNodes.NodeData]);
            elseif not(isempty(app.Tree_2.CheckedNodes))
                VarNames={app.Tree_2.CheckedNodes.Text};
                DataIn=table2array([app.Tree_2.CheckedNodes.NodeData]);
            else
                disp('nothing selected')
                return
            end
            DataIn(app.UITable.Data.Active==false,:)=nan;

            value = app.MethodDropDown.Value;
            switch value
                case 'Unique String'
                    %str2find=app.cmap_table.Data.Name(app.cmap_table.Data.Active);
                    where=          app.UITable.Data.(char(app.VariableDropDown.Value));
                    classNumber=nan(size(where));
                    c=0;
                    for n=1:numel(app.cmap_table.Data.Name)
                        if app.cmap_table.Data.Active(n)==true
                            c=c+1;
                            classNumber(ismember(where,app.cmap_table.Data.Name(n)))=  c;
                        end
                    end
                    %                     set2nan=~ismember(where,app.cmap_table.Data.Name(app.cmap_table.Data.Active));
                    %                     classNumber(set2nan)=nan;
                    DataIn=DataIn(ismember(where,app.cmap_table.Data.Name(app.cmap_table.Data.Active)),:);
                    classNumber=classNumber(ismember(where,app.cmap_table.Data.Name(app.cmap_table.Data.Active)),:);
                    cmap= app.cmap_table.Data.rgb(app.cmap_table.Data.Active,:);
                    %update_cmap(app,numel(str2find));


                case 'Numeric'
                    num2find=app.cmap_table.Data.Value;

                    where= app.UITable.Data.(char(app.VariableDropDown.Value));

                    if iscell(where)
                        where=[where{:}]';
                    end
                    classNumber=nan(size(where));
                    for n=1:numel(num2find)

                        classNumber(ismember(where,num2find(n)))=  n;
                    end

                    %                     set2nan=ismember(where,app.cmap_table.Data.Value(app.cmap_table.Data.Active));
                    %                     classNumber(set2nan)=nan;
                    DataIn=DataIn(ismember(where,app.cmap_table.Data.Value(app.cmap_table.Data.Active)),:);
                    classNumber=classNumber(ismember(where,app.cmap_table.Data.Value(app.cmap_table.Data.Active)),:);
                    cmap= app.cmap_table.Data.rgb(app.cmap_table.Data.Active,:);


                case 'NumClasses'
                    try
                        mapnr=1;
                        ClassMapNr=find(app.Data.Classes(mapnr).n_classes==app.ClassesSpinner.Value);
                        if isempty(ClassMapNr)
                            app.MaxClassesSpinner.Value=app.ClassesSpinner.Value;
                            app.MinClassesSpinner.Value=2;
                            RunClassTestButtonPushed(app)
                            ClassMapNr=find(app.Data.Classes(mapnr).n_classes==app.ClassesSpinner.Value);

                        end
                        classNumber=app.Data.Classes(mapnr).ClassMap(ClassMapNr).classNumber;


                    catch
                        disp('no classification')
                        [classNumber, classC, class_sumd]= kmeans(DataIn,app.ClassesSpinner.Value, 'replicates',app.IterationsSpinner.Value);
                    end


                    DataIn=DataIn(ismember(classNumber,app.cmap_table.Data.Value(app.cmap_table.Data.Active)),:);
                    classNumber=classNumber(ismember(classNumber,app.cmap_table.Data.Value(app.cmap_table.Data.Active)),:);
                    cmap= app.cmap_table.Data.rgb(app.cmap_table.Data.Active,:);

            end

            maxval=size(cmap,1);

            if app.PlotDataCheckBox.Value==true
                hFig=fcnCorrMatrixPlot_NG(DataIn,VarNames,'',classNumber,cmap,app.SymbolDropDown.Value);
                add_cbar(app,hFig,cmap,'Linear',maxval)
            end

            if app.PlotsqrtCheckBox.Value==true
                DataIn_sqrt=sqrt(DataIn);
                hFig=fcnCorrMatrixPlot_NG(DataIn_sqrt,VarNames,'',classNumber,cmap,app.SymbolDropDown.Value);
                add_cbar(app,hFig,cmap,'sqrt',maxval)
            end

            if app.PlotLogCheckBox.Value==true

                DataIn_log=log(DataIn);
                hFig=fcnCorrMatrixPlot_NG(DataIn_log,VarNames,'',classNumber,cmap,app.SymbolDropDown.Value);

                add_cbar(app,hFig,cmap,'log',maxval)
            end
            if app.PlotCLRCheckBox.Value==true
                DataIn_clr=clr(DataIn);
                hFig=fcnCorrMatrixPlot_NG(DataIn_clr,VarNames,'',classNumber,cmap,app.SymbolDropDown.Value);
                add_cbar(app,hFig,cmap,'CLR',maxval)

            end
        end

        % Button pushed function: RunClassTestButton
        function RunClassTestButtonPushed(app, event)
            tic
            if isempty(app.Data)
                return
            end
            if isempty(app.Tree_2.CheckedNodes)
                if isempty(app.Tree_2.SelectedNodes)
                    disp('nothing selected')
                    return
                else
                    app.Tree_2.CheckedNodes=app.Tree_2.SelectedNodes;
                end
            end

            VarNames={app.Tree_2.CheckedNodes.Text};
            DataIn =table2array([app.Tree_2.CheckedNodes.NodeData]);

            DataIn(app.UITable.Data.Active==false,:)=nan;


            if app.sqrt_CheckBox.Value==true
                DataIn(DataIn<0)=0;
                DataIn=sqrt(DataIn);
            end

            if app.CLR_CheckBox.Value==true
                DataIn(DataIn<0)=0;
                DataIn=clr(DataIn);
            end

            if app.LogCheckBox.Value==true
                DataIn=log(DataIn);
            end

            if app.Norm_CheckBox.Value==true
                DataIn=DataIn./max(DataIn);
            end

            if app.RandomSeedSpinner.Value==0
                rng('default');
            else
                rng(app.RandomSeedSpinner.Value);
            end

            if size(DataIn,1)<app.MaxClassesSpinner.Value
                app.MaxClassesSpinner.Value=size(DataIn,1)-2;
            end

            class_struc=struct();
            time_c(1)=toc;


            for k=app.MinClassesSpinner.Value:app.MaxClassesSpinner.Value
                %   [class_struc(k-app.MinClassesSpinner.Value+1).classNumber, class_struc(k-app.MinClassesSpinner.Value+1).classC, class_struc(k-app.MinClassesSpinner.Value+1).class_sumd]= kmeans(DataIn,k, 'replicates',app.IterationsSpinner.Value);
                [classNumber, classC, class_sumd]= kmeans(DataIn,k, 'replicates',app.IterationsSpinner.Value);

                if  strcmp(app.SortClassesDropDown.Value,'Integration')
                    to_sort=(trapz(classC,2));
                elseif  strcmp(app.SortClassesDropDown.Value,'n Pixel')
                    [to_sort, ~] =hist(classNumber,unique(classNumber(not(isnan(classNumber)))));
                elseif strcmp(app.SortClassesDropDown.Value,'C Distance')
                    %                     matrix=reshape(classNumber,[],app.mapsize(2))';
                    %                     for n=1:k
                    %                         [X,Y]= find(matrix==n);
                    %
                    %                         %  ;
                    %                         try
                    %                             to_sort(n) = mean(hypot(bsxfun(@minus, X, X'), bsxfun(@minus, Y, Y')),'all');
                    %                         catch
                    %                             'too big'
                    %                             to_sort(n)=mean(dist([X,Y]'),'all');
                    %                         end
                    %                     end
                    to_sort=class_sumd;

                    % [~, sort_id]=sort(to_sort,'ascend');
                end

                if exist('to_sort','var')
                    [~, sort_id]=sort(to_sort,'descend');

                    classNumber=-classNumber;
                    for nn=1:numel(sort_id)
                        classNumber(classNumber==-sort_id(nn))=nn;
                    end
                end
                class_struc(k-app.MinClassesSpinner.Value+1).classNumber=classNumber;
                class_struc(k-app.MinClassesSpinner.Value+1).classC=classC;
                class_struc(k-app.MinClassesSpinner.Value+1).class_sumd=class_sumd;

                sa(k-app.MinClassesSpinner.Value+1)= sum(class_struc(k-app.MinClassesSpinner.Value+1).class_sumd,"omitnan");
                time_c(k+1)=toc;

            end
            time_c=time_c-time_c(1);


            rng('default');
            cla(app.UIAxes)
            plot(app.UIAxes,app.MinClassesSpinner.Value:app.MaxClassesSpinner.Value,sa,'linewidth',2)
            app.UIAxes.Visible=true;


            ClassMapNr=0;
            mapnr=1;
            app.Data.ClassesNames{mapnr}=['Classification'  '_' num2str(mapnr)];%%num2str(numberofclasses)
            app.Data.Classes(mapnr).sa=[(app.MinClassesSpinner.Value:app.MaxClassesSpinner.Value)' sa'];
            ns=app.MinClassesSpinner.Value:app.MaxClassesSpinner.Value;
            for k=1:numel(app.MinClassesSpinner.Value:app.MaxClassesSpinner.Value)

                classNumber=class_struc(k).classNumber;
                ClassMapNr=ClassMapNr+1;
                app.Data.Classes(mapnr).ClassMap(ClassMapNr).classNumber=classNumber;
                app.Data.Classes(mapnr).ClassMapNames{ClassMapNr}=['Classmap_n' num2str(ns(k))];
                app.Data.Classes(mapnr).ClassMap(ClassMapNr).ClassesNames=cellstr(string(1:numel(unique(classNumber(not(isnan(classNumber))))))');
                app.Data.Classes(mapnr).n_classes(ClassMapNr)=ns(k);
            end

            save_raw=true;
            time=toc;

            if save_raw==true
                app.Data.Classes(mapnr).Classification_raw.Input=DataIn;
                app.Data.Classes(mapnr).Classification_raw.Results=class_struc;
                app.Data.Classes(mapnr).Classification_raw.time=time;
                app.Data.Classes(mapnr).Classification_raw.time_c=time_c(2:end);
            end

            update_cmap_table(app)
            update_class_tree(app)

            %             %%
            %
            %              % if double click
            %             hFig=figure(345);
            %             hold on
            %             mapnr=1;
            try
                ClassMapNr=find(app.Data.Classes(mapnr).n_classes==app.ClassesSpinner.Value);
            catch
                ClassMapNr=[];
            end
            if isempty(ClassMapNr)
                app.MaxClassesSpinner.Value=app.ClassesSpinner.Value;
                app.MinClassesSpinner.Value=2;
                RunClassTestButtonPushed(app)
                ClassMapNr=find(app.Data.Classes(mapnr).n_classes==app.ClassesSpinner.Value);
            end

            classNumber=app.Data.Classes(mapnr).ClassMap(ClassMapNr).classNumber;
            className=app.Data.Classes(mapnr).ClassMap(ClassMapNr).ClassesNames;

            %
            cmap= PlotMap_UpdateColormap(app,app.ClassesSpinner.Value);
            %
            SymbolSize=app.MarkerSizeEditField.Value;
            Symbol=  app.SymbolDropDown.Value;
            %             notfill={'+','*','.','x', '_','|'};
            %
            %             Y=table2array(app.Tree_2.SelectedNodes.NodeData);
            %
            %             for nclasses=1:numel(unique(classNumber(not(isnan(classNumber)))))
            %                 if ismember(Symbol,notfill)
            %                     plot(repmat(nclasses,size(Y(classNumber==nclasses,:))),Y(classNumber==nclasses,:),Symbol,'MarkerFaceColor',cmap(nclasses,:),'MarkerSize',SymbolSize,'MarkerEdgeColor',cmap(nclasses,:),'LineWidth',SymbolSize/10);
            %                 else
            %                     plot(repmat(nclasses,size(Y(classNumber==nclasses,:))),Y(classNumber==nclasses,:),Symbol,'MarkerFaceColor',cmap(nclasses,:),'MarkerSize',SymbolSize,'MarkerEdgeColor','k','LineWidth',SymbolSize/10);
            %                 end
            %             end
            if exist('event','var')


                DataIn =table2array([app.Tree_2.CheckedNodes.NodeData]);

                DataIn(app.UITable.Data.Active==false,:)=nan;


                if app.Norm_CheckBox.Value==true
                    DataIn=DataIn./max(DataIn);
                end

                if app.sqrt_CheckBox.Value==true
                    DataIn=sqrt(DataIn);
                end

                if app.CLR_CheckBox.Value==true
                    DataIn(DataIn<0)=0;
                    DataIn=clr(DataIn);
                end


                set_var=1;

                DataIn_plot.Active{set_var}=true(size(cmap,1),1);%:
                DataIn_plot.cmap{set_var}=cmap;%:
                DataIn_plot.Symbol{set_var}=repmat({Symbol},size(cmap,1),1);%:
                DataIn_plot.ClassNames{set_var}=className;
                DataIn_plot.SymbolSize{set_var}=repmat(SymbolSize,size(cmap,1),1);%:;%:

                DataIn_plot.DataMat{set_var}=DataIn;
                DataIn_plot.classNumber{set_var}=classNumber;

                DataIn_plot.VarNames={app.Tree_2.CheckedNodes.Text};

                CorPlotFun(DataIn_plot);
            end
        end

        % Button pushed function: DeleteSpectraButton
        function DeleteSpectraButtonPushed(app, event)
            if isempty(app.Data)
                return
            end

            %   if app.Data.Classes isnot emty warn
            app.Data.Classes=[];

            %ATH remove from classification results=[]
            selections = unique(app.UITable.Selection(:,1));
            app.UITable.Data(selections,:)=[];
            app.Data.Active.DataMat(selections,:)=[];
            update_data(app)
            update_MultiCor_trees(app,0)
        end

        % Button pushed function: DeleteColumn
        function DeleteColumnButtonPushed(app, event)
            if isempty(app.Data)
                return
            end
            selections = unique(app.UITable.Selection(:,2));
            app.Data.Active.DataMat(:,selections)=[];
            app.Data.Active.VariableNames(selections)=[];
            update_data(app)
        end

        % Button pushed function: Add2TableButton
        function Add2TableButtonPushed(app, event)
            if isempty(app.Data)
                return
            end

            if istable(app.Data.Active.DataMat)
                mapnr=1;
                ClassMapNr=find(app.Data.Classes(mapnr).n_classes==app.ClassesSpinner.Value);
                if isempty(ClassMapNr)
                    app.MaxClassesSpinner.Value=app.ClassesSpinner.Value;
                    app.MinClassesSpinner.Value=2;
                    RunClassTestButtonPushed(app)
                    ClassMapNr=find(app.Data.Classes(mapnr).n_classes==app.ClassesSpinner.Value);
                end
                classNumber=app.Data.Classes(mapnr).ClassMap(ClassMapNr).classNumber;

                app.Data.Active.DataMat.Classification(:)=classNumber;
            end

            update_data(app)


        end

        % Value changed function: ClassesSpinner
        function ClassesSpinnerValueChanged(app, event)
            if isempty(app.Data)
                return
            end
            value = app.ClassesSpinner.Value;
            update_cmap_table(app)
        end

        % Value changed function: MethodDropDown
        function MethodDropDownValueChanged(app, event)
            app.ColormapPreviewCheckBox.Value=false;

            value = app.MethodDropDown.Value;
            switch value
                case 'Unique String'
                    app.NumClassesPanel.Visible=false;
                    app.MaskSelectionButton.Visible=true;
                    app.ResetMaskButton.Visible=true;
                    app.UnmaskButton.Visible=true;
                    app.SelectAllButton_3.Visible=true;

                    if isempty(app.Data)
                        app.VariableDropDown.Items={};
                        return
                    else
                        app.VariableDropDown.Items=app.Data.Active.VariableNames(any(cellfun(@ischar,table2cell(app.UITable.Data))));
                        app.VariableDropDown.Visible=true;

                    end

                case 'Numeric'
                    app.NumClassesPanel.Visible=false;
                    app.MaskSelectionButton.Visible=true;
                    app.ResetMaskButton.Visible=true;
                    app.UnmaskButton.Visible=true;
                    app.SelectAllButton_3.Visible=true;

                    if isempty(app.Data)
                        app.VariableDropDown.Items={};
                        return
                    else
                        app.VariableDropDown.Items=app.Data.Active.VariableNames(any(cellfun(@isnumeric,table2cell(app.UITable.Data))));
                        app.VariableDropDown.Visible=true;
                    end
                case 'NumClasses'
                    app.NumClassesPanel.Visible=true;
                    app.VariableDropDown.Visible=false;
                    app.MaskSelectionButton.Visible=false;
                    app.ResetMaskButton.Visible=true;
                    app.UnmaskButton.Visible=false;
                    app.SelectAllButton_3.Visible=false;

                    if isempty(app.Data)
                        return
                    end
            end
            update_class_table(app)
            update_cmap_table(app)

        end

        % Value changed function: Options_ColormapDropDown
        function Options_ColormapDropDownValueChanged(app, event)
            value = app.Options_ColormapDropDown.Value;
            update_cmap_table(app)
            app.cmap_table.Data.rgb(:,:)=PlotMap_UpdateColormap(app,numel(app.cmap_table.Data.Value));


        end

        % Value changed function: VariableDropDown
        function VariableDropDownValueChanged(app, event)
            app.ColormapPreviewCheckBox.Value=false;
            value = app.VariableDropDown.Value;
            update_class_table(app)
            update_cmap_table(app)
        end

        % Selection changed function: VarSelectionTree
        function VarSelectionTreeSelectionChanged(app, event)
            %             selectedNodes = app.VarSelectionTree.SelectedNodes;
            cla(app.VarSelectionHistPlot)

            %
            %             histogram(app.VarSelectionHistPlot,table2array(app.VarSelectionTree.SelectedNodes.NodeData))
            %             title(app.VarSelectionHistPlot,{app.VarSelectionTree.SelectedNodes.Text, num2str(sum(not(isnan(table2array(app.VarSelectionTree.SelectedNodes.NodeData)))))},'Interpreter','none')
            selectedNodes = app.VarSelectionTree.SelectedNodes;

            cla(app.VarSelectionHistPlot)
            if isempty(selectedNodes)
                return
            end
            DataIn =table2array([app.VarSelectionTree.SelectedNodes.NodeData]);

            DataIn(app.UITable.Data.Active==false,:)=nan;

            histogram(app.VarSelectionHistPlot,DataIn)

            count_data=table2array(app.VarSelectionTree.SelectedNodes.NodeData);
            count_data_all= num2str(sum(not(isnan(count_data))));
            count_data_notmasked= num2str(sum(not(isnan(count_data(app.UITable.Data.Active(:)==true)))));


            title(app.VarSelectionHistPlot,{app.VarSelectionTree.SelectedNodes.Text, ['(' count_data_notmasked '/' count_data_all ')']},'Interpreter','none')


        end

        % Selection changed function: Tree_2
        function Tree_2SelectionChanged(app, event)
            selectedNodes = app.Tree_2.SelectedNodes;
            cla(app.UIAxes2_2)

            if isempty(selectedNodes)
                return
            end
            DataIn =table2array([app.Tree_2.SelectedNodes.NodeData]);

            DataIn(app.UITable.Data.Active==false,:)=nan;

            if app.Norm_CheckBox.Value==true
                DataIn=DataIn./max(DataIn);
            end

            if app.sqrt_CheckBox.Value==true
                DataIn=sqrt(DataIn);
            end

            if app.CLR_CheckBox.Value==true
                DataIn(DataIn<0)=0;
                DataIn(isnan(DataIn))=0;
                DataIn=clr([DataIn ones(size(DataIn))]);
                DataIn=DataIn(:,2);
                % DataIn=clr(DataIn);
            end

            histogram(app.UIAxes2_2,DataIn)

            count_data=table2array(app.Tree_2.SelectedNodes.NodeData);
            count_data_all= num2str(sum(not(isnan(count_data))));
            count_data_notmasked= num2str(sum(not(isnan(count_data(app.UITable.Data.Active(:)==true)))));


            title(app.UIAxes2_2,{app.Tree_2.SelectedNodes.Text, ['(' count_data_notmasked '/' count_data_all ')']},'Interpreter','none')


        end

        % Cell edit callback: ClassTable
        function ClassTableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            app.ClassTable.Data(indices(:,1),indices(:,2))={newData};
            update_cmap_table(app)


        end

        % Cell edit callback: cmap_table
        function cmap_tableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            app.cmap_table.Data(indices(:,1),indices(:,2))={newData};


        end

        % Value changed function: ColormapPreviewCheckBox, 
        % InvertColormapCheckBox
        function ColormapPreviewCheckBoxValueChanged(app, event)
            update_cmap_table(app)

        end

        % Button down function: cmap_table
        function cmap_tableButtonDown(app, event)

            click_threshold_s = 0.5;
            if isempty(app.click_time)
                app.click_time = tic();
            else
                time_between_clicks = toc( app.click_time );
                app.click_time = tic();
                if time_between_clicks < click_threshold_s
                    % if field==Color
                    c = uisetcolor;  % Enter desired callback / action here.
                    figure(app.GeoCPlotUIFigure)
                    if any(isequal(unique(app.cmap_table.Selection(:,2)),4))
                        %    app.cmap_table.Data.rgb(app.cmap_table.Selection(1,:))=repmat(c',1,numel(unique(app.cmap_table.Selection(1,:))));
                        app.cmap_table.Data.rgb(app.cmap_table.Selection(:,1),:)=repmat(c,numel(unique(app.cmap_table.Selection(:,1))),1);
                        try
                            app.Data.CustomColorMap=app.cmap_table.Data.rgb;

                            app.Options_ColormapDropDown.ItemsData{end}=app.Data.CustomColorMap;
                            app.Options_ColormapDropDown.Value=app.Data.CustomColorMap;

                        catch ME
                            ME
                        end
                        %app.Options_ColormapDropDown.Value=app.Options_ColormapDropDown.ItemsData{end}
                        update_cmap_table(app)
                    end

                    %  end
                end % if double-click
            end % if empty
        end

        % Button pushed function: SelectAllButton
        function SelectAllButtonPushed(app, event)
            app.VarSelectionTree.CheckedNodes=app.VarSelectionTree.Children;
        end

        % Button pushed function: DeselectAllButton
        function DeselectAllButtonPushed(app, event)
            app.VarSelectionTree.CheckedNodes=[];
        end

        % Button pushed function: SelectAllButton_2
        function SelectAllButton_2Pushed(app, event)
            app.Tree_2.CheckedNodes=app.Tree_2.Children;
        end

        % Button pushed function: DeselectAllButton_2
        function DeselectAllButton_2Pushed(app, event)
            app.Tree_2.CheckedNodes=[];

        end

        % Button pushed function: StoreDataButton
        function StoreDataButtonPushed(app, event)
            if isempty(app.Data)
                return
            end
            app.ColorPreviewCheckBox.Value=false;
            update_cmap_table(app)

            if not(isempty(app.VarSelectionTree.CheckedNodes))
                %% added 20240515
                thx=[app.VarSelectionTree.CheckedNodes.NodeData];
                if any(not(varfun(@isnumeric,thx,'output','uniform')))
                    not_number=find(not(varfun(@isnumeric,thx,'output','uniform')));
                    thx_new=varfun(@str2double,thx(:,not(varfun(@isnumeric,thx,'output','uniform'))));
                    for n=1:size(thx_new,2)
                        thx.(not_number(n))=thx_new.(n);
                    end
                end
                %%
                VarNames={app.VarSelectionTree.CheckedNodes.Text}; %ath here all names to tree but check the same
                %  DataIn=table2array([app.VarSelectionTree.CheckedNodes.NodeData]);
                DataIn=table2array(thx);

            elseif not(isempty(app.Tree_2.CheckedNodes))
                VarNames={app.Tree_2.CheckedNodes.Text};
                app.VarSelectionTree.CheckedNodes= app.VarSelectionTree.Children(ismember({app.VarSelectionTree.Children.Text}, {app.Tree_2.CheckedNodes.Text}));
                DataIn=table2array([app.Tree_2.CheckedNodes.NodeData]);

            else
                disp('nothing selected')
                return
            end

            %  Active(checked nodes)=true
            % DataIn(mask,:)=nan
            DataIn(app.UITable.Data.Active==false,:)=nan;

            value = app.MethodDropDown.Value;
            switch value
                case 'Unique String'
                    className=unique(app.UITable.Data.(char(app.VariableDropDown.Value)));
                    className=className(app.ClassTable.Data.Active);

                    str2find=app.cmap_table.Data.Name(app.cmap_table.Data.Active);
                    where=          app.UITable.Data.(char(app.VariableDropDown.Value));
                    classNumber=nan(size(where));
                    c=0;
                    for n=1:numel(app.cmap_table.Data.Name)
                        if app.cmap_table.Data.Active(n)==true
                            c=c+1;
                            classNumber(ismember(where,app.cmap_table.Data.Name(n)))=  c;
                        end
                    end
                    DataIn=DataIn(ismember(where,app.cmap_table.Data.Name(app.cmap_table.Data.Active)),:);
                    classNumber=classNumber(ismember(where,app.cmap_table.Data.Name(app.cmap_table.Data.Active)),:);
                    cmap= app.cmap_table.Data.rgb(app.cmap_table.Data.Active,:);
                case 'Numeric'
                    className=string(unique(app.UITable.Data.(char(app.VariableDropDown.Value))));
                    className=className(app.ClassTable.Data.Active);

                    num2find=app.cmap_table.Data.Value;

                    where= app.UITable.Data.(char(app.VariableDropDown.Value));

                    if iscell(where)
                        where=[where{:}]';
                    end
                    classNumber=nan(size(where));
                    for n=1:numel(num2find)

                        classNumber(ismember(where,num2find(n)))=  n;
                    end
                    DataIn=DataIn(ismember(where,app.cmap_table.Data.Value(app.cmap_table.Data.Active)),:);

                    classNumber=classNumber(ismember(where,app.cmap_table.Data.Value(app.cmap_table.Data.Active)),:);
                    cmap= app.cmap_table.Data.rgb(app.cmap_table.Data.Active,:);

                case 'NumClasses'

                    mapnr=1;
                    ClassMapNr=find(app.Data.Classes(mapnr).n_classes==app.ClassesSpinner.Value);
                    if isempty(ClassMapNr)
                        app.MaxClassesSpinner.Value=app.ClassesSpinner.Value;
                        app.MinClassesSpinner.Value=2;
                        RunClassTestButtonPushed(app)
                        ClassMapNr=find(app.Data.Classes(mapnr).n_classes==app.ClassesSpinner.Value);
                    end
                    classNumber=app.Data.Classes(mapnr).ClassMap(ClassMapNr).classNumber;
                    className=app.Data.Classes(mapnr).ClassMap(ClassMapNr).ClassesNames(app.cmap_table.Data.Active,:);
                    DataIn=DataIn(ismember(classNumber,app.cmap_table.Data.Value(app.cmap_table.Data.Active)),:);
                    classNumber=classNumber(ismember(classNumber,app.cmap_table.Data.Value(app.cmap_table.Data.Active)),:);
                    cmap= app.cmap_table.Data.rgb(app.cmap_table.Data.Active,:);

            end

            %             className=className(ismember(className,app.cmap_table.Data.Name));

            className=app.cmap_table.Data.Name(app.cmap_table.Data.Active);


            Symbol(1:size(cmap,1),1)={app.SymbolDropDown.Value};
            %             if strcmp(app.SymbolDropDown.Value,'.')
            %                 SymbolSize(1:size(cmap,1),1)=1;
            %             else
            %                 SymbolSize(1:size(cmap,1),1)=10;
            %             end
            SymbolSize(1:size(cmap,1),1)=app.MarkerSizeEditField.Value;

            Active(1:size(cmap,1),1)=true;


            if app.new_set==true  || numel(app.Data.MultiCorPlot)==0 || isempty(fieldnames(app.Data.MultiCorPlot))
                if  isempty((app.Data.MultiCorPlot)) || isempty(fieldnames(app.Data.MultiCorPlot))
                    app.ActiveSetSpinner.Value=1;
                else
                    app.ActiveSetSpinner.Value=numel(app.Data.MultiCorPlot)+1;
                end
                app.new_set=false;

            else
                app.ActiveSetSpinner.Value=numel(app.Data.MultiCorPlot);
            end

            if   isfield(app.Data,'MultiCorPlot') && numel(app.Data.MultiCorPlot)>=app.ActiveSetSpinner.Value && isfield(app.Data.MultiCorPlot(app.ActiveSetSpinner.Value),'VarNames') && not(isempty(app.Data.MultiCorPlot(app.ActiveSetSpinner.Value).VarNames))&&isequal(app.Data.MultiCorPlot(app.ActiveSetSpinner.Value).VarNames,VarNames)

                app.Data.MultiCorPlot(app.ActiveSetSpinner.Value).DataMat{end+1}=DataIn;
                app.Data.MultiCorPlot(app.ActiveSetSpinner.Value).cmap{end+1}=cmap;
                app.Data.MultiCorPlot(app.ActiveSetSpinner.Value).Symbol{end+1}=Symbol;
                app.Data.MultiCorPlot(app.ActiveSetSpinner.Value).ClassNames{end+1}=className;
                app.Data.MultiCorPlot(app.ActiveSetSpinner.Value).classNumber{end+1}=classNumber;

                app.Data.MultiCorPlot(app.ActiveSetSpinner.Value).SymbolSize{end+1}=SymbolSize;
                app.Data.MultiCorPlot(app.ActiveSetSpinner.Value).Active{end+1}=Active;
                selected_MultiCor=app.ActiveSetSpinner.Value;

            else

                app.Data.MultiCorPlot(app.ActiveSetSpinner.Value).VarNames=VarNames;
                app.Data.MultiCorPlot(app.ActiveSetSpinner.Value).DataMat{1}=DataIn;

                app.Data.MultiCorPlot(app.ActiveSetSpinner.Value).cmap{1}=cmap;
                app.Data.MultiCorPlot(app.ActiveSetSpinner.Value).Symbol{1}=Symbol;
                app.Data.MultiCorPlot(app.ActiveSetSpinner.Value).ClassNames{1}=className;
                app.Data.MultiCorPlot(app.ActiveSetSpinner.Value).classNumber{1}=classNumber;
                app.Data.MultiCorPlot(app.ActiveSetSpinner.Value).SymbolSize{1}=SymbolSize;
                app.Data.MultiCorPlot(app.ActiveSetSpinner.Value).Active{1}=Active;
                selected_MultiCor=app.ActiveSetSpinner.Value;
            end


            update_MultiCor_trees(app,0)
            update_MultiCor_table(app,selected_MultiCor)


        end

        % Button pushed function: StoreClassMedianButton
        function StoreClassMedianButtonPushed(app, event)
            if isempty(app.Data)
                return
            end
            app.ColorPreviewCheckBox.Value=false;

            if not(isempty(app.VarSelectionTree.CheckedNodes))
                VarNames={app.VarSelectionTree.CheckedNodes.Text}; %ath here all names to tree but check the same
                DataIn=table2array([app.VarSelectionTree.CheckedNodes.NodeData]);
            elseif not(isempty(app.Tree_2.CheckedNodes))
                VarNames={app.Tree_2.CheckedNodes.Text};
                app.VarSelectionTree.CheckedNodes= app.VarSelectionTree.Children(ismember({app.VarSelectionTree.Children.Text}, {app.Tree_2.CheckedNodes.Text}));
                DataIn=table2array([app.Tree_2.CheckedNodes.NodeData]);
            else
                disp('nothing selected')
                return
            end

            %  Active(checked nodes)=true
            % DataIn(mask,:)=nan
            DataIn(app.UITable.Data.Active==false,:)=nan;

            value = app.MethodDropDown.Value;
            switch value
                case 'Unique String'


                    className=unique(app.UITable.Data.(char(app.VariableDropDown.Value)));
                    className=className(app.ClassTable.Data.Active);

                    str2find=app.cmap_table.Data.Name(app.cmap_table.Data.Active);
                    where=          app.UITable.Data.(char(app.VariableDropDown.Value));
                    classNumber=nan(size(where));
                    c=0;
                    for n=1:numel(app.cmap_table.Data.Name)
                        if app.cmap_table.Data.Active(n)==true
                            c=c+1;
                            classNumber(ismember(where,app.cmap_table.Data.Name(n)))=  c;
                        end
                    end
                    DataIn=DataIn(ismember(where,app.cmap_table.Data.Name(app.cmap_table.Data.Active)),:);
                    classNumber=classNumber(ismember(where,app.cmap_table.Data.Name(app.cmap_table.Data.Active)),:);
                    cmap= app.cmap_table.Data.rgb(app.cmap_table.Data.Active,:);
                case 'Numeric'
                    className=string(unique(app.UITable.Data.(char(app.VariableDropDown.Value))));
                    className=className(app.ClassTable.Data.Active);

                    num2find=app.cmap_table.Data.Value;

                    where= app.UITable.Data.(char(app.VariableDropDown.Value));

                    if iscell(where)
                        where=[where{:}]';
                    end
                    classNumber=nan(size(where));
                    for n=1:numel(num2find)
                        classNumber(ismember(where,num2find(n)))=  n;
                    end
                    DataIn=DataIn(ismember(where,app.cmap_table.Data.Value(app.cmap_table.Data.Active)),:);
                    classNumber=classNumber(ismember(where,app.cmap_table.Data.Value(app.cmap_table.Data.Active)),:);
                    cmap= app.cmap_table.Data.rgb(app.cmap_table.Data.Active,:);

                case 'NumClasses'

                    mapnr=1;
                    ClassMapNr=find(app.Data.Classes(mapnr).n_classes==app.ClassesSpinner.Value);
                    if isempty(ClassMapNr)
                        app.MaxClassesSpinner.Value=app.ClassesSpinner.Value;
                        app.MinClassesSpinner.Value=2;
                        RunClassTestButtonPushed(app)
                        ClassMapNr=find(app.Data.Classes(mapnr).n_classes==app.ClassesSpinner.Value);

                    end
                    classNumber=app.Data.Classes(mapnr).ClassMap(ClassMapNr).classNumber;
                    className=app.Data.Classes(mapnr).ClassMap(ClassMapNr).ClassesNames(app.cmap_table.Data.Active,:);
                    DataIn=DataIn(ismember(classNumber,app.cmap_table.Data.Value(app.cmap_table.Data.Active)),:);
                    classNumber=classNumber(ismember(classNumber,app.cmap_table.Data.Value(app.cmap_table.Data.Active)),:);
                    cmap= app.cmap_table.Data.rgb(app.cmap_table.Data.Active,:);

            end

            className=className(ismember(className,app.cmap_table.Data.Name));
            className=app.cmap_table.Data.Name(app.cmap_table.Data.Active);

            Symbol(1:size(cmap,1),1)={app.SymbolDropDown.Value};

            SymbolSize(1:size(cmap,1),1)=app.MarkerSizeEditField.Value;

            Active(1:size(cmap,1),1)=true;

            classNumber_u=unique(classNumber);
            DataIn_m=nan(numel(classNumber_u),size(DataIn,2));
            for n=1:numel(classNumber_u)
                DataIn_m(n,:)=  median(DataIn(classNumber==n,:),1,'omitnan');
                if numel(DataIn(classNumber==n,:)) == sum(isnan(DataIn(classNumber==n,:)))
                    DataIn_m(n,:)=nan;
                end
            end
            DataIn_m(:,not(any(not(isnan(DataIn)))))   =nan;

            DataIn=DataIn_m;
            classNumber=classNumber_u;

            className=join([className repmat({' Median'},size(className))]);

            if app.new_set==true  || numel(app.Data.MultiCorPlot)==0
                app.ActiveSetSpinner.Value=numel(app.Data.MultiCorPlot)+1;
                app.new_set=false;

            else
                app.ActiveSetSpinner.Value=numel(app.Data.MultiCorPlot);
            end

            if isfield(app.Data,'MultiCorPlot') && numel(app.Data.MultiCorPlot)>=app.ActiveSetSpinner.Value && isfield(app.Data.MultiCorPlot(app.ActiveSetSpinner.Value),'VarNames') && not(isempty(app.Data.MultiCorPlot(app.ActiveSetSpinner.Value).VarNames))&&isequal(app.Data.MultiCorPlot(app.ActiveSetSpinner.Value).VarNames,VarNames)
                app.Data.MultiCorPlot(app.ActiveSetSpinner.Value).DataMat{end+1}=DataIn;
                app.Data.MultiCorPlot(app.ActiveSetSpinner.Value).cmap{end+1}=cmap;
                app.Data.MultiCorPlot(app.ActiveSetSpinner.Value).Symbol{end+1}=Symbol;
                app.Data.MultiCorPlot(app.ActiveSetSpinner.Value).ClassNames{end+1}=className;
                app.Data.MultiCorPlot(app.ActiveSetSpinner.Value).classNumber{end+1}=classNumber;

                app.Data.MultiCorPlot(app.ActiveSetSpinner.Value).SymbolSize{end+1}=SymbolSize;
                app.Data.MultiCorPlot(app.ActiveSetSpinner.Value).Active{end+1}=Active;
                selected_MultiCor=app.ActiveSetSpinner.Value;

            else

                app.Data.MultiCorPlot(app.ActiveSetSpinner.Value).VarNames=VarNames;
                app.Data.MultiCorPlot(app.ActiveSetSpinner.Value).DataMat{1}=DataIn;

                app.Data.MultiCorPlot(app.ActiveSetSpinner.Value).cmap{1}=cmap;
                app.Data.MultiCorPlot(app.ActiveSetSpinner.Value).Symbol{1}=Symbol;
                app.Data.MultiCorPlot(app.ActiveSetSpinner.Value).ClassNames{1}=className;
                app.Data.MultiCorPlot(app.ActiveSetSpinner.Value).classNumber{1}=classNumber;
                app.Data.MultiCorPlot(app.ActiveSetSpinner.Value).SymbolSize{1}=SymbolSize;
                app.Data.MultiCorPlot(app.ActiveSetSpinner.Value).Active{1}=Active;
                selected_MultiCor=app.ActiveSetSpinner.Value;
            end


            update_MultiCor_trees(app,0)
            update_MultiCor_table(app,selected_MultiCor)

        end

        % Button pushed function: StoreClassMeanButton
        function StoreClassMeanButtonPushed(app, event)
            if isempty(app.Data)
                return
            end
            app.ColorPreviewCheckBox.Value=false;

            if not(isempty(app.VarSelectionTree.CheckedNodes))
                VarNames={app.VarSelectionTree.CheckedNodes.Text}; %ath here all names to tree but check the same
                DataIn=table2array([app.VarSelectionTree.CheckedNodes.NodeData]);
            elseif not(isempty(app.Tree_2.CheckedNodes))
                VarNames={app.Tree_2.CheckedNodes.Text};
                app.VarSelectionTree.CheckedNodes= app.VarSelectionTree.Children(ismember({app.VarSelectionTree.Children.Text}, {app.Tree_2.CheckedNodes.Text}));
                DataIn=table2array([app.Tree_2.CheckedNodes.NodeData]);
            else
                disp('nothing selected')
                return
            end

            %  Active(checked nodes)=true
            % DataIn(mask,:)=nan
            DataIn(app.UITable.Data.Active==false,:)=nan;

            value = app.MethodDropDown.Value;
            switch value
                case 'Unique String'


                    className=unique(app.UITable.Data.(char(app.VariableDropDown.Value)));
                    className=className(app.ClassTable.Data.Active);

                    str2find=app.cmap_table.Data.Name(app.cmap_table.Data.Active);
                    where=          app.UITable.Data.(char(app.VariableDropDown.Value));
                    classNumber=nan(size(where));
                    c=0;
                    for n=1:numel(app.cmap_table.Data.Name)
                        if app.cmap_table.Data.Active(n)==true
                            c=c+1;
                            classNumber(ismember(where,app.cmap_table.Data.Name(n)))=  c;
                        end
                    end
                    DataIn=DataIn(ismember(where,app.cmap_table.Data.Name(app.cmap_table.Data.Active)),:);
                    classNumber=classNumber(ismember(where,app.cmap_table.Data.Name(app.cmap_table.Data.Active)),:);
                    cmap= app.cmap_table.Data.rgb(app.cmap_table.Data.Active,:);
                case 'Numeric'
                    className=string(unique(app.UITable.Data.(char(app.VariableDropDown.Value))));
                    className=className(app.ClassTable.Data.Active);

                    num2find=app.cmap_table.Data.Value;

                    where= app.UITable.Data.(char(app.VariableDropDown.Value));

                    if iscell(where)
                        where=[where{:}]';
                    end
                    classNumber=nan(size(where));
                    for n=1:numel(num2find)
                        classNumber(ismember(where,num2find(n)))=  n;
                    end
                    DataIn=DataIn(ismember(where,app.cmap_table.Data.Value(app.cmap_table.Data.Active)),:);
                    classNumber=classNumber(ismember(where,app.cmap_table.Data.Value(app.cmap_table.Data.Active)),:);
                    cmap= app.cmap_table.Data.rgb(app.cmap_table.Data.Active,:);

                case 'NumClasses'

                    mapnr=1;
                    ClassMapNr=find(app.Data.Classes(mapnr).n_classes==app.ClassesSpinner.Value);
                    if isempty(ClassMapNr)
                        app.MaxClassesSpinner.Value=app.ClassesSpinner.Value;
                        app.MinClassesSpinner.Value=2;
                        RunClassTestButtonPushed(app)
                        ClassMapNr=find(app.Data.Classes(mapnr).n_classes==app.ClassesSpinner.Value);

                    end
                    classNumber=app.Data.Classes(mapnr).ClassMap(ClassMapNr).classNumber;
                    className=app.Data.Classes(mapnr).ClassMap(ClassMapNr).ClassesNames;
                    DataIn=DataIn(ismember(classNumber,app.cmap_table.Data.Value(app.cmap_table.Data.Active)),:);
                    classNumber=classNumber(ismember(classNumber,app.cmap_table.Data.Value(app.cmap_table.Data.Active)),:);
                    cmap= app.cmap_table.Data.rgb(app.cmap_table.Data.Active,:);

            end

            className=className(ismember(className,app.cmap_table.Data.Name));
            className=app.cmap_table.Data.Name(app.cmap_table.Data.Active);

            Symbol(1:size(cmap,1),1)={app.SymbolDropDown.Value};

            SymbolSize(1:size(cmap,1),1)=app.MarkerSizeEditField.Value;


            Active(1:size(cmap,1),1)=true;

            classNumber_u=unique(classNumber);
            DataIn_m=nan(numel(classNumber_u),size(DataIn,2));
            for n=1:numel(classNumber_u)
                DataIn_m(n,:)=  mean(DataIn(classNumber==n,:),1,'omitnan');

                if numel(DataIn(classNumber==n,:)) == sum(isnan(DataIn(classNumber==n,:)))
                    DataIn_m(n,:)=nan;
                end
                %   DataIn_m(n,:)=  median(DataIn(classNumber==n,:),1,'omitnan');

            end
            DataIn_m(:,not(any(not(isnan(DataIn)))))   =nan;

            DataIn=DataIn_m;
            classNumber=classNumber_u;

            className=join([className repmat({' Mean'},size(className))]);

            if app.new_set==true  || numel(app.Data.MultiCorPlot)==0
                app.ActiveSetSpinner.Value=numel(app.Data.MultiCorPlot)+1;
                app.new_set=false;
            else
                app.ActiveSetSpinner.Value=numel(app.Data.MultiCorPlot);
            end

            if app.ActiveSetSpinner.Value>0 &&isfield(app.Data,'MultiCorPlot') && numel(app.Data.MultiCorPlot)>=app.ActiveSetSpinner.Value && isfield(app.Data.MultiCorPlot(app.ActiveSetSpinner.Value),'VarNames') && not(isempty(app.Data.MultiCorPlot(app.ActiveSetSpinner.Value).VarNames))&&isequal(app.Data.MultiCorPlot(app.ActiveSetSpinner.Value).VarNames,VarNames)
                app.Data.MultiCorPlot(app.ActiveSetSpinner.Value).DataMat{end+1}=DataIn;
                app.Data.MultiCorPlot(app.ActiveSetSpinner.Value).cmap{end+1}=cmap;
                app.Data.MultiCorPlot(app.ActiveSetSpinner.Value).Symbol{end+1}=Symbol;
                app.Data.MultiCorPlot(app.ActiveSetSpinner.Value).ClassNames{end+1}=className;
                app.Data.MultiCorPlot(app.ActiveSetSpinner.Value).classNumber{end+1}=classNumber;

                app.Data.MultiCorPlot(app.ActiveSetSpinner.Value).SymbolSize{end+1}=SymbolSize;
                app.Data.MultiCorPlot(app.ActiveSetSpinner.Value).Active{end+1}=Active;
                selected_MultiCor=app.ActiveSetSpinner.Value;

            else
                if app.ActiveSetSpinner.Value==0
                    app.ActiveSetSpinner.Value=1;
                end
                app.Data.MultiCorPlot(app.ActiveSetSpinner.Value).VarNames=VarNames;
                app.Data.MultiCorPlot(app.ActiveSetSpinner.Value).DataMat{1}=DataIn;

                app.Data.MultiCorPlot(app.ActiveSetSpinner.Value).cmap{1}=cmap;
                app.Data.MultiCorPlot(app.ActiveSetSpinner.Value).Symbol{1}=Symbol;
                app.Data.MultiCorPlot(app.ActiveSetSpinner.Value).ClassNames{1}=className;
                app.Data.MultiCorPlot(app.ActiveSetSpinner.Value).classNumber{1}=classNumber;
                app.Data.MultiCorPlot(app.ActiveSetSpinner.Value).SymbolSize{1}=SymbolSize;
                app.Data.MultiCorPlot(app.ActiveSetSpinner.Value).Active{1}=Active;
                selected_MultiCor=app.ActiveSetSpinner.Value;
            end


            update_MultiCor_trees(app,0)
            update_MultiCor_table(app,selected_MultiCor)


        end

        % Callback function: VarSelectionTree
        function VarSelectionTreeCheckedNodesChanged(app, event)
            checkedNodes = app.VarSelectionTree.CheckedNodes;

            app.new_set=true;
            %             if app.ActiveSetSpinner.Value>0 &&isfield(app.Data,'MultiCorPlot') && numel(app.Data.MultiCorPlot)>=app.ActiveSetSpinner.Value && isfield(app.Data.MultiCorPlot(app.ActiveSetSpinner.Value),'DataMat') && not(isempty(app.Data.MultiCorPlot(app.ActiveSetSpinner.Value).DataMat))
            %                 app.ActiveSetSpinner.Value=1+ app.ActiveSetSpinner.Value;
            %             end

        end

        % Button pushed function: NewCorrelationPlotButton
        function NewCorrelationPlotButtonPushed(app, event)
            update_MultiCor_table(app,app.ActiveSetSpinner.Value)
            if isempty(app.Data.MultiCorPlot)
                return
            end
            DataIn=app.Data.MultiCorPlot(app.ActiveSetSpinner.Value);
            DataIn.link=app.LinkAxesCheckBox.Value;
            DataIn.ExcludeEmpty=app.ExcludeEmptyCheckBox.Value;

DataIn.gap=app.GapDropDown.Value;
            checkedNodes_var = app.Tree_MultiCor_Groups.CheckedNodes;
            checkedNodes_groups = app.Tree_MultiCor_VarName.CheckedNodes;


            if not(isempty(checkedNodes_groups))
                checkedNodes_groups = app.Tree_MultiCor_VarName.CheckedNodes(cellfun(@isnumeric,{app.Tree_MultiCor_VarName.CheckedNodes.NodeData}));

                nd=reshape([checkedNodes_groups.NodeData],2,[])';
                set_var=unique(nd(:,1));
                for n=1:numel(set_var)
                    subset_var=nd(nd(:,1)==set_var(n),2);
                    n_sg=1:numel(DataIn.VarNames);

                    not_subgroup=n_sg(not(ismember(n_sg,subset_var)));
                    %                      DataIn.DataMat{set_var}(:,not_subgroup)=[];
                    %                      DataIn.VarNames(not_subgroup)=[];
                    for k=1:numel(DataIn.DataMat)
                        DataIn.DataMat{k}(:,not_subgroup)=[];
                    end
                    DataIn.VarNames(not_subgroup)=[];

                end
            end

            if not(isempty(checkedNodes_var))

                checkedNodes_var = app.Tree_MultiCor_Groups.CheckedNodes(cellfun(@isnumeric,{app.Tree_MultiCor_Groups.CheckedNodes.NodeData}));

                nd=reshape([checkedNodes_var.NodeData],2,[])';

                nodes={app.Tree_MultiCor_Groups.Children(:).Children};
                nodes=cat(1,nodes{:});
                nd_all=reshape([nodes.NodeData],2,[])';

                for n=1:size(unique(nd_all(:,1)))
                    test= ismember(nd_all(nd_all(:,1)==n,:),nd,'rows');
                    % subset_var=test;

                    not_subgroup=find(not(test));

                    DataIn.Active{n}(not_subgroup)=[];%:
                    DataIn.cmap{n}(not_subgroup,:)=[];%:
                    DataIn.Symbol{n}(not_subgroup)=[];%:
                    DataIn.ClassNames{n}(not_subgroup)=[];%:
                    DataIn.SymbolSize{n}(not_subgroup)=[];%:


                    DataIn.DataMat{n}(ismember(DataIn.classNumber{n},not_subgroup),:)=[];
                    DataIn.classNumber{n}(ismember(DataIn.classNumber{n},not_subgroup))=[];
                    % EdgeColor
                end

            end

            if  app.ExcludeEmptyCheckBox.Value==true

            end

            if app.PlotDataCheckBox.Value==true
                CorPlotFun(DataIn);
            end

            if app.PlotsqrtCheckBox.Value==true
                DataIn_sqrt=DataIn;
                for n=1:numel(DataIn_sqrt.DataMat)
                    DataIn_sqrt.DataMat{n}=sqrt(DataIn_sqrt.DataMat{n});
                end
                CorPlotFun(DataIn_sqrt);
                ts=sgtitle('sqrt');
                set(ts, 'Interpreter', 'none')
            end

            if app.PlotLogCheckBox.Value==true
 %               DataIn_log=DataIn;
%                 for n=1:numel(DataIn_log.DataMat)
%                     DataIn_log.DataMat{n}=log(DataIn_log.DataMat{n});
%                 end
DataIn.LogState='log';
                CorPlotFun(DataIn);
                ts=sgtitle('log');
                set(ts, 'Interpreter', 'none')
            end
            if app.PlotCLRCheckBox.Value==true
                DataIn_clr=DataIn;

                for n=1:numel(DataIn_clr.DataMat)
                    DataIn_clr.DataMat{n}=clr(DataIn_clr.DataMat{n});
                end
                CorPlotFun(DataIn_clr);
                ts=sgtitle('clr');
                set(ts, 'Interpreter', 'none')

            end


        end

        % Button pushed function: ClearAllButton
        function ClearAllButtonPushed(app, event)
            checkedNodes_var = app.Tree_MultiCor_Groups.CheckedNodes;
            app.Data.MultiCorPlot=[];
            app.ActiveSetSpinner.Value=0;
            update_MultiCor_table(app,app.ActiveSetSpinner.Value)
            update_MultiCor_trees(app,0)

            app.ActiveSetSpinner.Visible=false;
            app.ActiveSetSpinnerLabel.Visible=false;
        end

        % Button pushed function: RemoveGroupButton
        function RemoveGroupButtonPushed(app, event)
            %app.group multi tree selected nodes n2delete=[NodeData(:,1)]
            % checkedNodes_groups = Nodesunique(reshape([app.Tree_MultiCor_VarName.SelectedNodes.NodeData],2,[])');
            if isempty(app.Tree_MultiCor_Groups.SelectedNodes)
                return
            end

            if cellfun(@isnumeric,{app.Tree_MultiCor_Groups.SelectedNodes.NodeData})
                n2delete=[app.Tree_MultiCor_Groups.SelectedNodes.NodeData];
                cn=app.Data.MultiCorPlot(app.ActiveSetSpinner.Value).classNumber{n2delete(1)};
                app.Data.MultiCorPlot(app.ActiveSetSpinner.Value).DataMat{n2delete(1)}(:,n2delete(2))=[];
                app.Data.MultiCorPlot(app.ActiveSetSpinner.Value).DataMat{n2delete(1)}(cn==n2delete(2),:)=[];

                cn(cn==n2delete(2))=[];
                cn(cn>n2delete(2))=cn(cn>n2delete(2))-1;
                app.Data.MultiCorPlot(app.ActiveSetSpinner.Value).classNumber{n2delete(1)}=cn;


                app.Data.MultiCorPlot(app.ActiveSetSpinner.Value).DataMat{n2delete(1)}(:,n2delete(2))=[];
                if size(app.Data.MultiCorPlot(app.ActiveSetSpinner.Value).DataMat{n2delete(1)},2)==0
                    app.Data.MultiCorPlot(app.ActiveSetSpinner.Value).DataMat(n2delete(1))=[];
                end
                app.Data.MultiCorPlot(app.ActiveSetSpinner.Value).cmap{n2delete(1)}(n2delete(2),:)=[];
                app.Data.MultiCorPlot(app.ActiveSetSpinner.Value).Symbol{n2delete(1)}(n2delete(2))=[];
                app.Data.MultiCorPlot(app.ActiveSetSpinner.Value).SymbolSize{n2delete(1)}(n2delete(2))=[];
                app.Data.MultiCorPlot(app.ActiveSetSpinner.Value).Active{n2delete(1)}(n2delete(2))=[];
                app.Data.MultiCorPlot(app.ActiveSetSpinner.Value).ClassNames{n2delete(1)}(n2delete(2))=[];


            else
                n2delete =find(strcmp(app.Tree_MultiCor_Groups.SelectedNodes.Text,{app.Tree_MultiCor_Groups.Children.Text}));

                app.Data.MultiCorPlot(app.ActiveSetSpinner.Value).DataMat(n2delete)=[];
                app.Data.MultiCorPlot(app.ActiveSetSpinner.Value).cmap(n2delete)=[];
                app.Data.MultiCorPlot(app.ActiveSetSpinner.Value).Symbol(n2delete)=[];
                app.Data.MultiCorPlot(app.ActiveSetSpinner.Value).classNumber(n2delete)=[];
                app.Data.MultiCorPlot(app.ActiveSetSpinner.Value).ClassNames(n2delete)=[];
                app.Data.MultiCorPlot(app.ActiveSetSpinner.Value).SymbolSize(n2delete)=[];
                app.Data.MultiCorPlot(app.ActiveSetSpinner.Value).Active(n2delete)=[];
                app.ActiveSetSpinner.Value=app.ActiveSetSpinner.Value-1;

                if app.ActiveSetSpinner.Value==0 && not (isempty( app.Data.MultiCorPlot))
                    app.ActiveSetSpinner.Value=1;
                end
            end


            if isempty( app.Data.MultiCorPlot(app.ActiveSetSpinner.Value).DataMat)
                app.ActiveSetSpinnerValueChanged(app)
            else
                update_MultiCor_trees(app,'Groups')
                update_MultiCor_table(app,app.ActiveSetSpinner.Value)
            end
            %%


        end

        % Callback function
        function AddtoAdvancedPlotButtonPushed(app, event)
            %       if isvalid figure handle
            % plot on top of figureA
        end

        % Cell edit callback: MultiCor_table
        function MultiCor_tableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            groups=find(strcmp(app.MultiCor_table.Data.Group(indices(1)),{app.Tree_MultiCor_Groups.Children.Text}));
            for n=1:numel(groups)
                group=groups(n);
                subnames={app.Tree_MultiCor_Groups.Children(group).Children.Text};

                fn=app.MultiCor_table.ColumnName{indices(2)};
                selected_subs=0;
                if strcmp(fn,'Symbol')
                    if not(ismember(newData,{'o', '+', '*', '.', 'x', '_', '|', 's', 'd', '^', 'v', '>', '<', 'p', 'h', 'none'}))
                        message={'Only the following options are allowed: ','''o'', ''+'', ''*'', ''.'', ''x'', ''_'', ''|'', ''s'', ''d'', ''^'', ''v'', ''>'', ''<'', ''p'', ''h'', ''none'''};
                        uialert(app.GeoCPlotUIFigure,message,'Wrong Input','Icon','Error');
                        update_MultiCor_table(app,app.ActiveSetSpinner.Value)
                        return
                    end
                end
                if strcmp(fn,'Name')
                    fn='ClassNames';
                    pos2insert=find(ismember(subnames,event.PreviousData));
                else
                    all=false;
                    if not(isempty(app.MultiCor_table.Selection)) && numel(app.MultiCor_table.Selection(:,1))>1
                        answer = questdlg({'Do you want to edit all selected rows?'},...
                            '','Yes','No','Cancle','');
                        switch answer
                            case 'Yes'
                                all=true;
                            case 'No'
                                all=false;
                            case 'Cancle'
                                update_MultiCor_table(app,app.ActiveSetSpinner.Value)
                                return
                        end
                    end
                    if all==true
                        selected_subs=app.MultiCor_table.Data.Name(app.MultiCor_table.Selection(:,1));
                    else
                        selected_subs= app.MultiCor_table.Data.Name(indices(1));
                    end
                    pos2insert=find(ismember(subnames,selected_subs));
                    %  pos2insert=find(ismember(subnames,event.PreviousData));
                end
                %ath add logic test for allowed symbols and values
                %          pos2insert= find(find(strcmp(app.MultiCor_table.Data.Group(indices(1)),app.MultiCor_table.Data.Group))==indices(1));
                %                             all_groups={app.Tree_MultiCor_Groups.Children.Text};
                %
                %                             group=groups(n);
                %                             app.MultiCor_table.Data.Group(group)
                %selected_subs= app.MultiCor_table.Data.Name(indices(1));
                %
                if iscell(app.Data.MultiCorPlot(app.ActiveSetSpinner.Value).(char(fn)){group}(pos2insert))
                    app.Data.MultiCorPlot(app.ActiveSetSpinner.Value).(char(fn)){group}(pos2insert)={newData};
                else
                    app.Data.MultiCorPlot(app.ActiveSetSpinner.Value).(char(fn)){group}(pos2insert)=newData;
                    % app.Data.MultiCorPlot(app.ActiveSetSpinner.Value).(char(fn)){indices(1)}=newData;
                end
            end
            if strcmp(fn,'ClassNames')
                update_MultiCor_trees(app,'Groups')
            end

            if size(selected_subs,1)>1
                update_MultiCor_table(app,app.ActiveSetSpinner.Value)
            end
        end

        % Button down function: MultiCor_table
        function MultiCor_tableButtonDown(app, event)


            click_threshold_s = 0.5;
            if isempty(app.click_time)
                app.click_time = tic();
            else

                time_between_clicks = toc( app.click_time );
                app.click_time = tic();
                if time_between_clicks < click_threshold_s
                    % if field==Color
                    c = uisetcolor;  % Enter desired callback / action here.
                    figure(app.GeoCPlotUIFigure)
                    if any(isequal(unique(app.MultiCor_table.Selection(:,2)),6))
                        %    app.MultiCor_table.Data.rgb(app.MultiCor_table.Selection(1,:))=repmat(c',1,numel(unique(app.MultiCor_table.Selection(1,:))));

                        app.MultiCor_table.Data.rgb(app.MultiCor_table.Selection(:,1),:)=repmat(c,numel(unique(app.MultiCor_table.Selection(:,1))),1);
                        indices=unique(app.MultiCor_table.Selection(:,1));

                        groups=find(ismember({app.Tree_MultiCor_Groups.Children.Text},app.MultiCor_table.Data.Group(indices)));%unique(app.MultiCor_table.Data.Group(ismember(app.MultiCor_table.Data.Group(indices),{app.Tree_MultiCor_Groups.Children.Text})));
                        %find(strcmp(app.MultiCor_table.Data.Group(indices),{app.Tree_MultiCor_Groups.Children.Text}));
                        all_groups={app.Tree_MultiCor_Groups.Children.Text};
                        for n=1:numel(groups)
                            group=groups(n);
                            app.MultiCor_table.Data.Group(group)
                            subnames={app.Tree_MultiCor_Groups.Children(group).Children.Text};
                            % strcmp(app.MultiCor_table.Data.Group(indices),all_groups(group))
                            selected_subs= app.MultiCor_table.Data.Name(indices);
                            pos2insert=find(ismember(subnames,selected_subs));
                            %                       pos2insert= find(ismember({app.Tree_MultiCor_Groups.Children(group).Children.Text},app.MultiCor_table.Data.Name(find(strcmp(app.MultiCor_table.Data.Group(indices),app.MultiCor_table.Data.Group(group))))));%find(find(strcmp(app.MultiCor_table.Data.Group(indices),app.MultiCor_table.Data.Group))==indices);
                            app.Data.MultiCorPlot(app.ActiveSetSpinner.Value).cmap{group}(pos2insert,:)=repmat(c,numel(unique(pos2insert)),1);
                        end

                        if app.ColorPreviewCheckBox.Value==true
                            for n=1:numel(app.MultiCor_table.Data.Active)
                                s = uistyle('BackgroundColor',app.MultiCor_table.Data.rgb(n,:));
                                addStyle(app.MultiCor_table,s,'row',n)
                            end
                            s = uistyle('BackgroundColor',[1 1 1 ]);
                            addStyle(app.MultiCor_table,s,'column',[1:5 7])
                        else
                            s = uistyle('BackgroundColor',[1 1 1 ]);

                            addStyle(app.MultiCor_table,s,'column',1:7)
                        end
                    end
                    %  end
                end % if double-click
            end % if empty


        end

        % Value changed function: PlotCLRCheckBox_2
        function PlotCLRCheckBox_2ValueChanged(app, event)
            app.PlotCLRCheckBox.Value = app.PlotCLRCheckBox_2.Value;
            app.PlotCLRCheckBox_3.Value = app.PlotCLRCheckBox_2.Value;
        end

        % Value changed function: PlotLogCheckBox_2
        function PlotLogCheckBox_2ValueChanged(app, event)
            app.PlotLogCheckBox.Value = app.PlotLogCheckBox_2.Value;
            app.PlotLogCheckBox_3.Value = app.PlotLogCheckBox_2.Value;
        end

        % Value changed function: PlotDataCheckBox_2
        function PlotDataCheckBox_2ValueChanged(app, event)
            app.PlotDataCheckBox.Value = app.PlotDataCheckBox_2.Value;
            app.PlotDataCheckBox_3.Value = app.PlotDataCheckBox_2.Value;
        end

        % Value changed function: PlotDataCheckBox
        function PlotDataCheckBoxValueChanged(app, event)
            app.PlotDataCheckBox_2.Value = app.PlotDataCheckBox.Value;
            app.PlotDataCheckBox_3.Value = app.PlotDataCheckBox.Value;
        end

        % Value changed function: PlotLogCheckBox
        function PlotLogCheckBoxValueChanged(app, event)
            app.PlotLogCheckBox_2.Value = app.PlotLogCheckBox.Value;
            app.PlotLogCheckBox_3.Value = app.PlotLogCheckBox.Value;

        end

        % Value changed function: PlotCLRCheckBox
        function PlotCLRCheckBoxValueChanged(app, event)
            app.PlotCLRCheckBox_2.Value = app.PlotCLRCheckBox.Value;
            app.PlotCLRCheckBox_3.Value = app.PlotCLRCheckBox.Value;

        end

        % Value changed function: PlotsqrtCheckBox
        function PlotsqrtCheckBoxValueChanged(app, event)
            app.PlotsqrtCheckBox_2.Value= app.PlotsqrtCheckBox.Value;
            app.PlotsqrtCheckBox_3.Value= app.PlotsqrtCheckBox.Value;

        end

        % Value changed function: PlotsqrtCheckBox_2
        function PlotsqrtCheckBox_2ValueChanged(app, event)
            app.PlotsqrtCheckBox.Value= app.PlotsqrtCheckBox_2.Value;
            app.PlotsqrtCheckBox_3.Value= app.PlotsqrtCheckBox_2.Value;


        end

        % Button pushed function: ResetMaskButton
        function ResetMaskButtonPushed(app, event)

            if not(isempty(app.ClassTable.Data))
                app.ClassTable.Data.Active(:)=true;
            end
            app.Data.Active.DataMat.Active(:)=true;
            app.UITable.Data.Active(:)=true;
            update_class_table(app)
            update_data(app)
            update_cmap_table(app)

        end

        % Button pushed function: MaskSelectionButton
        function MaskSelectionButtonPushed(app, event)

            %            old_mask= app.ClassTable.Data.Active;
            %                        ResetMaskButtonPushed(app)
            %                            app.ClassTable.Data.Active=old_mask;


            old_mask=app.Data.Active.DataMat.Active;
            % app.UITable.Data.(char(app.VariableDropDown.Value))
            value = app.MethodDropDown.Value;
            if any(sum(app.ClassTable.Data.Active)==numel(app.ClassTable.Data.Active)) &&not(isempty(app.ClassTable.Selection))
                selection=unique(app.ClassTable.Selection(:,1));
            else
                selection=find(app.ClassTable.Data.Active);
            end
            switch value
                case 'Unique String'
                    %str2find=app.ClassTable.Data.Name(app.ClassTable.Data.Active);
                    where=          app.UITable.Data.(char(app.VariableDropDown.Value));
                    classNumber=nan(size(where));
                    c=0;
                    for n=1:numel(app.ClassTable.Data.Name)
                        if app.ClassTable.Data.Active(n)==true
                            c=c+1;
                            classNumber(ismember(where,app.ClassTable.Data.Name(n)))=  c;
                        end
                    end
                    %                     set2nan=~ismember(where,app.ClassTable.Data.Name(app.ClassTable.Data.Active));
                    %                     classNumber(set2nan)=nan;
                    new_mask=ismember(where,app.ClassTable.Data.Name(selection));
                case 'Numeric'
                    where= app.UITable.Data.(char(app.VariableDropDown.Value));
                    if iscell(where)
                        where=[where{:}]';
                    end


                    new_mask=ismember(where,app.ClassTable.Data.Value(selection));

                case 'NumClasses'
                    try
                        mapnr=1;
                        ClassMapNr=find(app.Data.Classes(mapnr).n_classes==app.ClassesSpinner.Value);
                        if isempty(ClassMapNr)
                            app.MaxClassesSpinner.Value=app.ClassesSpinner.Value;
                            app.MinClassesSpinner.Value=2;
                            RunClassTestButtonPushed(app)
                            ClassMapNr=find(app.Data.Classes(mapnr).n_classes==app.ClassesSpinner.Value);
                        end
                        classNumber=app.Data.Classes(mapnr).ClassMap(ClassMapNr).classNumber;
                    catch
                        disp('no classification')
                        [classNumber, ~, ~]= kmeans(DataIn,app.ClassesSpinner.Value, 'replicates',app.IterationsSpinner.Value);
                    end
                    new_mask=ismember(classNumber,app.ClassTable.Data.Value(selection));
            end


            %             app.Data.Active.DataMat.Active(new_mask)=true;
            %             app.UITable.Data.Active(new_mask)=true;

            app.Data.Active.DataMat.Active(:)=(old_mask==true&new_mask==true);
            app.UITable.Data.Active(:)=(old_mask==true&new_mask==true);


            ids =1:numel(app.ClassTable.Data.Active);
            app.ClassTable.Data.Active(selection)=true;
            app.ClassTable.Data.Active(not(ismember(ids,ids(selection))))=false;

            update_class_table(app)

            update_data(app)
            update_cmap_table(app)

        end

        % Button pushed function: UnmaskButton
        function UnmaskButtonPushed(app, event)
            old_mask=app.Data.Active.DataMat.Active;

            value = app.MethodDropDown.Value;
            if any(sum(app.ClassTable.Data.Active)==numel(app.ClassTable.Data.Active)) &&not(isempty(app.ClassTable.Selection))
                selection=unique(app.ClassTable.Selection(:,1));
            else
                if not(isempty(unique(app.ClassTable.Selection(:,1))))
                    app.ClassTable.Data.Active(unique(app.ClassTable.Selection(:,1)))=true;
                end
                selection=app.ClassTable.Data.Active;
            end
            switch value
                case 'Unique String'
                    %str2find=app.ClassTable.Data.Name(app.ClassTable.Data.Active);
                    where=          app.UITable.Data.(char(app.VariableDropDown.Value));
                    classNumber=nan(size(where));
                    c=0;
                    for n=1:numel(app.ClassTable.Data.Name)
                        if app.ClassTable.Data.Active(n)==true
                            c=c+1;
                            classNumber(ismember(where,app.ClassTable.Data.Name(n)))=  c;
                        end
                    end
                    %                     set2nan=~ismember(where,app.ClassTable.Data.Name(app.ClassTable.Data.Active));
                    %                     classNumber(set2nan)=nan;
                    new_mask=ismember(where,app.ClassTable.Data.Name(selection));
                case 'Numeric'
                    where= app.UITable.Data.(char(app.VariableDropDown.Value));
                    if iscell(where)
                        where=[where{:}]';
                    end


                    new_mask=ismember(where,app.ClassTable.Data.Value(selection));

                case 'NumClasses'
                    try
                        mapnr=1;
                        ClassMapNr=find(app.Data.Classes(mapnr).n_classes==app.ClassesSpinner.Value);
                        if isempty(ClassMapNr)
                            app.MaxClassesSpinner.Value=app.ClassesSpinner.Value;
                            app.MinClassesSpinner.Value=2;
                            RunClassTestButtonPushed(app)
                            ClassMapNr=find(app.Data.Classes(mapnr).n_classes==app.ClassesSpinner.Value);
                        end
                        classNumber=app.Data.Classes(mapnr).ClassMap(ClassMapNr).classNumber;
                    catch
                        disp('no classification')
                        [classNumber, ~, ~]= kmeans(DataIn,app.ClassesSpinner.Value, 'replicates',app.IterationsSpinner.Value);
                    end
                    new_mask=ismember(classNumber,app.ClassTable.Data.Value(selection));
            end


            %  mask=old_mask==true & not(old_mask==false & new_mask==true);
            app.Data.Active.DataMat.Active(new_mask)=true;
            app.UITable.Data.Active(new_mask)=true;

            update_class_table(app)
            update_data(app)
            update_cmap_table(app)

        end

        % Button pushed function: SelectAllButton_3
        function SelectAllButton_3Pushed(app, event)
            % app.ClassTable.Data.Active(:)={true};

            if any(not(app.ClassTable.Data.Active(:)))
                app.ClassTable.Data.Active(:)=(true);
            else
                app.ClassTable.Data.Active(:)=(false);
            end

            update_cmap_table(app)
        end

        % Button pushed function: moveupButton
        function moveupButtonPushed(app, event)
            if isempty(app.Tree_MultiCor_Groups.SelectedNodes)
                return
            end

            if cellfun(@isnumeric,{app.Tree_MultiCor_Groups.SelectedNodes.NodeData})
                n2move=[app.Tree_MultiCor_Groups.SelectedNodes.NodeData];

                app.Data.MultiCorPlot(app.ActiveSetSpinner.Value).DataMat{n2move(1)}(:,n2move(2))=[];
                if size(app.Data.MultiCorPlot(app.ActiveSetSpinner.Value).DataMat{n2move(1)},2)==0
                    app.Data.MultiCorPlot(app.ActiveSetSpinner.Value).DataMat(n2move(1))=[];
                end
                app.Data.MultiCorPlot(app.ActiveSetSpinner.Value).cmap{n2move(1)}(n2move(2),:)=[];
                app.Data.MultiCorPlot(app.ActiveSetSpinner.Value).Symbol{n2move(1)}(n2move(2))=[];
                app.Data.MultiCorPlot(app.ActiveSetSpinner.Value).SymbolSize{n2move(1)}(n2move(2))=[];
                app.Data.MultiCorPlot(app.ActiveSetSpinner.Value).Active{n2move(1)}(n2move(2))=[];
                app.Data.MultiCorPlot(app.ActiveSetSpinner.Value).ClassNames{n2move(1)}(n2move(2))=[];

                cn=app.Data.MultiCorPlot(app.ActiveSetSpinner.Value).classNumber{n2move(1)};

                cn(cn==n2move(2))=nan;
                cn(cn>n2move(2))=cn(cn>n2move(2))-1;
                app.Data.MultiCorPlot(app.ActiveSetSpinner.Value).classNumber{n2move(1)}=cn;


            else
                n2move =find(strcmp(app.Tree_MultiCor_Groups.SelectedNodes.Text,{app.Tree_MultiCor_Groups.Children.Text}));
                if n2move==1
                    return
                else
                    n_groups=1:numel(app.Data.MultiCorPlot(app.ActiveSetSpinner.Value).DataMat);
                    before=n_groups(not(n2move==n_groups)&n_groups<n2move-1);
                    after=n_groups(not(n2move==n_groups)&n_groups>n2move-2);

                    app.Data.MultiCorPlot(app.ActiveSetSpinner.Value).DataMat=app.Data.MultiCorPlot(app.ActiveSetSpinner.Value).DataMat([before n2move after]);
                    app.Data.MultiCorPlot(app.ActiveSetSpinner.Value).cmap=app.Data.MultiCorPlot(app.ActiveSetSpinner.Value).cmap([before n2move after]);
                    app.Data.MultiCorPlot(app.ActiveSetSpinner.Value).Symbol=app.Data.MultiCorPlot(app.ActiveSetSpinner.Value).Symbol([before n2move after]);
                    app.Data.MultiCorPlot(app.ActiveSetSpinner.Value).classNumber=app.Data.MultiCorPlot(app.ActiveSetSpinner.Value).classNumber([before n2move after]);
                    app.Data.MultiCorPlot(app.ActiveSetSpinner.Value).ClassNames=app.Data.MultiCorPlot(app.ActiveSetSpinner.Value).ClassNames([before n2move after]);
                    app.Data.MultiCorPlot(app.ActiveSetSpinner.Value).SymbolSize=app.Data.MultiCorPlot(app.ActiveSetSpinner.Value).SymbolSize([before n2move after]);
                    app.Data.MultiCorPlot(app.ActiveSetSpinner.Value).Active=app.Data.MultiCorPlot(app.ActiveSetSpinner.Value).Active([before n2move after]);

                end
                update_MultiCor_trees(app,'Groups')
                update_MultiCor_table(app,app.ActiveSetSpinner.Value)
            end
        end

        % Button pushed function: movedownButton
        function movedownButtonPushed(app, event)
            if isempty(app.Tree_MultiCor_Groups.SelectedNodes)
                return
            end

            if cellfun(@isnumeric,{app.Tree_MultiCor_Groups.SelectedNodes.NodeData})

            else
                n2move =find(strcmp(app.Tree_MultiCor_Groups.SelectedNodes.Text,{app.Tree_MultiCor_Groups.Children.Text}));
                if n2move==numel(app.Data.MultiCorPlot(app.ActiveSetSpinner.Value).DataMat)
                    return
                else
                    n_groups=1:numel(app.Data.MultiCorPlot(app.ActiveSetSpinner.Value).DataMat);
                    before=n_groups(not(n2move==n_groups)&n_groups<n2move+2);
                    after=n_groups(not(n2move==n_groups)&n_groups>n2move+1);

                    app.Data.MultiCorPlot(app.ActiveSetSpinner.Value).DataMat=app.Data.MultiCorPlot(app.ActiveSetSpinner.Value).DataMat([before n2move after]);
                    app.Data.MultiCorPlot(app.ActiveSetSpinner.Value).cmap=app.Data.MultiCorPlot(app.ActiveSetSpinner.Value).cmap([before n2move after]);
                    app.Data.MultiCorPlot(app.ActiveSetSpinner.Value).Symbol=app.Data.MultiCorPlot(app.ActiveSetSpinner.Value).Symbol([before n2move after]);
                    app.Data.MultiCorPlot(app.ActiveSetSpinner.Value).classNumber=app.Data.MultiCorPlot(app.ActiveSetSpinner.Value).classNumber([before n2move after]);
                    app.Data.MultiCorPlot(app.ActiveSetSpinner.Value).ClassNames=app.Data.MultiCorPlot(app.ActiveSetSpinner.Value).ClassNames([before n2move after]);
                    app.Data.MultiCorPlot(app.ActiveSetSpinner.Value).SymbolSize=app.Data.MultiCorPlot(app.ActiveSetSpinner.Value).SymbolSize([before n2move after]);
                    app.Data.MultiCorPlot(app.ActiveSetSpinner.Value).Active=app.Data.MultiCorPlot(app.ActiveSetSpinner.Value).Active([before n2move after]);

                end
                update_MultiCor_trees(app,'Groups')
                update_MultiCor_table(app,app.ActiveSetSpinner.Value)
            end
        end

        % Callback function: Tree_MultiCor_Groups
        function Tree_MultiCor_GroupsCheckedNodesChanged(app, event)
            %             checkedNodes = app.Tree_MultiCor_Groups.CheckedNodes;
            %             if isempty(checkedNodes)
            %                 return
            %             end
            %
            %             checkedNodes= app.Tree_MultiCor_Groups.CheckedNodes(cellfun(@isnumeric,{app.Tree_MultiCor_Groups.CheckedNodes.NodeData}));
            %             nd=reshape([checkedNodes.NodeData],2,[])';
            %
            %
            %

            update_MultiCor_table(app,app.ActiveSetSpinner.Value)

        end

        % Value changed function: SymbolDropDown
        function SymbolDropDownValueChanged(app, event)
            value = app.SymbolDropDown.Value;
            if strcmp(value,'.')
                app.MarkerSizeEditField.Value=5;
            else
                app.MarkerSizeEditField.Value=7.5;
            end
        end

        % Value changed function: ActiveSetSpinner
        function ActiveSetSpinnerValueChanged(app, event)
            value = app.ActiveSetSpinner.Value;

            if numel(app.Data.MultiCorPlot)<value
                app.ActiveSetSpinner.Value=numel(app.Data.MultiCorPlot);
            end
            if value<=0
                if numel(app.Data.MultiCorPlot)>0
                    app.ActiveSetSpinner.Value=1;
                else
                    app.ActiveSetSpinner.Value=0;
                end
            end
            update_MultiCor_table(app,app.ActiveSetSpinner.Value)
            update_MultiCor_trees(app,'Groups')


        end

        % Button pushed function: MaskClassButton
        function MaskClassButtonPushed(app, event)
            RunClassTestButtonPushed(app,0)
            mapnr=1;

            ClassMapNr=find(app.Data.Classes(mapnr).n_classes==app.ClassesSpinner.Value);
            classNumber=app.Data.Classes(mapnr).ClassMap(ClassMapNr).classNumber;
            className=app.Data.Classes(mapnr).ClassMap(ClassMapNr).ClassesNames;

            [indx,tf] = listdlg('PromptString',{'Select Classes to Exclude'},...
                'SelectionMode','multiple','ListString',className);


            app.Data.Active.DataMat.Active(ismember(classNumber,indx))=false;
            app.UITable.Data.Active(ismember(classNumber,indx))=false;
            update_data(app)
        end

        % Button pushed function: ExportTableButton
        function ExportTableButtonPushed(app, event)
            try
                [filename, filepath] = uiputfile('*.xls', 'Save the table:');
                FileName = fullfile(filepath, filename);
                writematrix(app.Maptable.Data,FileName,'WriteVariableNames',true,'WriteRowNames',true,'xls')
            catch
                0
            end
        end

        % Button pushed function: SelectButton_2
        function SelectButton_2Pushed(app, event)
            if isempty(app.Tree_2.SelectedNodes)
                return
            end
            %   if all app.Tree_2.SelectedNodes are checked uncheck else check
            app.Tree_2.CheckedNodes=app.Tree_2.SelectedNodes;

        end

        % Button pushed function: SelectButton
        function SelectButtonPushed(app, event)
            update_data(app)
            if isempty(app.VarSelectionTree.SelectedNodes)
                return
            end
            %   if all app.VarSelectionTree.SelectedNodes are checked uncheck else check
            app.VarSelectionTree.CheckedNodes=[app.VarSelectionTree.SelectedNodes app.VarSelectionTree.CheckedNodes];
        end

        % Value changed function: ColorPreviewCheckBox
        function ColorPreviewCheckBoxValueChanged(app, event)
            update_MultiCor_table(app,app.ActiveSetSpinner.Value)
        end

        % Button pushed function: NewTernPlotButton
        function NewTernPlotButtonPushed(app, event)
            update_MultiCor_table(app,app.ActiveSetSpinner.Value)
            if isempty(app.Data.MultiCorPlot)
                return
            end
            DataIn=app.Data.MultiCorPlot(app.ActiveSetSpinner.Value);
            DataIn.link=app.LinkAxesCheckBox.Value;
            DataIn.ExcludeEmpty=app.ExcludeEmptyCheckBox.Value;


            checkedNodes_var = app.Tree_MultiCor_Groups.CheckedNodes;
            checkedNodes_groups = app.Tree_MultiCor_VarName.CheckedNodes;


            if not(isempty(checkedNodes_groups))
                checkedNodes_groups = app.Tree_MultiCor_VarName.CheckedNodes(cellfun(@isnumeric,{app.Tree_MultiCor_VarName.CheckedNodes.NodeData}));

                nd=reshape([checkedNodes_groups.NodeData],2,[])';
                set_var=unique(nd(:,1));
                for n=1:numel(set_var)
                    subset_var=nd(nd(:,1)==set_var(n),2);
                    n_sg=1:numel(DataIn.VarNames);

                    not_subgroup=n_sg(not(ismember(n_sg,subset_var)));
                    %                      DataIn.DataMat{set_var}(:,not_subgroup)=[];
                    %                      DataIn.VarNames(not_subgroup)=[];
                    for k=1:numel(DataIn.DataMat)
                        DataIn.DataMat{k}(:,not_subgroup)=[];
                    end
                    DataIn.VarNames(not_subgroup)=[];

                end
            end

            if not(isempty(checkedNodes_var))

                checkedNodes_var = app.Tree_MultiCor_Groups.CheckedNodes(cellfun(@isnumeric,{app.Tree_MultiCor_Groups.CheckedNodes.NodeData}));

                nd=reshape([checkedNodes_var.NodeData],2,[])';

                nodes={app.Tree_MultiCor_Groups.Children(:).Children};
                nodes=cat(1,nodes{:});
                nd_all=reshape([nodes.NodeData],2,[])';

                for n=1:size(unique(nd_all(:,1)))
                    test= ismember(nd_all(nd_all(:,1)==n,:),nd,'rows');
                    % subset_var=test;

                    not_subgroup=find(not(test));

                    DataIn.Active{n}(not_subgroup)=[];%:
                    DataIn.cmap{n}(not_subgroup,:)=[];%:
                    DataIn.Symbol{n}(not_subgroup)=[];%:
                    DataIn.ClassNames{n}(not_subgroup)=[];%:
                    DataIn.SymbolSize{n}(not_subgroup)=[];%:


                    DataIn.DataMat{n}(ismember(DataIn.classNumber{n},not_subgroup),:)=[];
                    DataIn.classNumber{n}(ismember(DataIn.classNumber{n},not_subgroup))=[];
                    % EdgeColor
                end

            end

            if  app.ExcludeEmptyCheckBox.Value==true

            end

            if app.Norm_CheckBox_2.Value==true
                for n=1:numel(DataIn.DataMat)
                    DataIn.DataMat{n}=normalize(DataIn.DataMat{n},'range');
                end
            end

            if app.PlotDataCheckBox.Value==true%
                PlotTernFun(DataIn,app.MaxSubpltSpinner.Value);
            end

            if app.PlotsqrtCheckBox.Value==true
                DataIn_sqrt=DataIn;
                for n=1:numel(DataIn_sqrt.DataMat)
                    DataIn_sqrt.DataMat{n}=normalize( DataIn_sqrt.DataMat{n},'range');
                    DataIn_sqrt.DataMat{n}=sqrt(DataIn_sqrt.DataMat{n});

                end
                PlotTernFun(DataIn_sqrt,app.MaxSubpltSpinner.Value);
                ts=sgtitle('sqrt');
                set(ts, 'Interpreter', 'none')
            end

            if app.PlotLogCheckBox.Value==true
                DataIn_log=DataIn;
                for n=1:numel(DataIn_log.DataMat)
                    DataIn_log.DataMat{n}=normalize( DataIn_log.DataMat{n},'range');
                    DataIn_log.DataMat{n}=log(DataIn_log.DataMat{n});

                end
                PlotTernFun(DataIn_log,app.MaxSubpltSpinner.Value);

            end
            if app.PlotCLRCheckBox.Value==true
                DataIn_clr=DataIn;

                for n=1:numel(DataIn_clr.DataMat)
                    DataIn_clr.DataMat{n}=normalize( DataIn_clr.DataMat{n},'range');
                    DataIn_clr.DataMat{n}=clr(DataIn_clr.DataMat{n});
                end

                PlotTernFun(DataIn_clr,app.MaxSubpltSpinner.Value);
            end


        end

        % Value changed function: PlotDataCheckBox_3
        function PlotDataCheckBox_3ValueChanged(app, event)
            app.PlotDataCheckBox.Value= app.PlotDataCheckBox_3.Value;
            app.PlotDataCheckBox_2.Value= app.PlotDataCheckBox_3.Value;

        end

        % Value changed function: PlotLogCheckBox_3
        function PlotLogCheckBox_3ValueChanged(app, event)

            app.PlotLogCheckBox.Value= app.PlotLogCheckBox_3.Value;
            app.PlotLogCheckBox_2.Value= app.PlotLogCheckBox_3.Value;

        end

        % Value changed function: PlotCLRCheckBox_3
        function PlotCLRCheckBox_3ValueChanged(app, event)
            app.PlotCLRCheckBox.Value= app.PlotCLRCheckBox_3.Value;
            app.PlotCLRCheckBox_2.Value= app.PlotCLRCheckBox_3.Value;

        end

        % Value changed function: PlotsqrtCheckBox_3
        function PlotsqrtCheckBox_3ValueChanged(app, event)
            value = app.PlotsqrtCheckBox_3.Value;
            app.PlotsqrtCheckBox.Value= app.PlotsqrtCheckBox_3.Value;
            app.PlotsqrtCheckBox_2.Value= app.PlotsqrtCheckBox_3.Value;

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.GeoCPlotUIFigure = uifigure('Visible', 'off');
            app.GeoCPlotUIFigure.Name = 'GeoCPlot';
            app.GeoCPlotUIFigure.Icon = 'symbols_1_GeoCPlot.png';
            app.GridLayout2 = uigridlayout(app.GeoCPlotUIFigure);
            app.TabGroup = uitabgroup(app.GridLayout2);
            app.StartTab = uitab(app.TabGroup);
            app.StartTab.Title = 'Start';
            app.GridLayout = uigridlayout(app.StartTab);
            app.LoadDataButton = uibutton(app.GridLayout, 'push');
            app.LoadDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDataButtonPushed, true);
            app.LoadDataButton.Text = 'Load Data';
            app.UITable = uitable(app.GridLayout);
            app.UITable.ColumnName = '';
            app.UITable.RowName = {};
            app.UITable.ColumnSortable = true;
            app.SearchButton = uibutton(app.GridLayout, 'push');
            app.SearchButton.Visible = 'off';
            app.SearchButton.Text = 'Search';
            app.SearchEditFieldLabel = uilabel(app.GridLayout);
            app.SearchEditFieldLabel.Visible = 'off';
            app.SearchEditFieldLabel.Text = 'Search';
            app.SearchEditField = uieditfield(app.GridLayout, 'text');
            app.SearchEditField.Visible = 'off';
            app.DeleteColumn = uibutton(app.GridLayout, 'push');
            app.DeleteColumn.ButtonPushedFcn = createCallbackFcn(app, @DeleteColumnButtonPushed, true);
            app.DeleteColumn.Icon = 'symbols_1_Delete Column-33.png';
            app.DeleteColumn.Tooltip = {'Delete Column from Database Table'};
            app.DeleteColumn.Text = '';
            app.DeleteSpectraButton = uibutton(app.GridLayout, 'push');
            app.DeleteSpectraButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteSpectraButtonPushed, true);
            app.DeleteSpectraButton.Icon = 'Delete.png';
            app.DeleteSpectraButton.Tooltip = {'Delete Spectra from Database'};
            app.DeleteSpectraButton.Text = '';
            app.ExportTableButton = uibutton(app.GridLayout, 'push');
            app.ExportTableButton.ButtonPushedFcn = createCallbackFcn(app, @ExportTableButtonPushed, true);
            app.ExportTableButton.Visible = 'off';
            app.ExportTableButton.Text = 'Export Table';
            app.ClassificationTab = uitab(app.TabGroup);
            app.ClassificationTab.Title = 'Classification';
            app.GridLayout4 = uigridlayout(app.ClassificationTab);
            app.ClassTable = uitable(app.GridLayout4);
            app.ClassTable.ColumnName = {'Active'; 'Name'; 'Value'; 'n'};
            app.ClassTable.RowName = {};
            app.ClassTable.ColumnSortable = [true true true false];
            app.ClassTable.ColumnEditable = [true true false];
            app.ClassTable.CellEditCallback = createCallbackFcn(app, @ClassTableCellEdit, true);
            app.MethodDropDownLabel = uilabel(app.GridLayout4);
            app.MethodDropDownLabel.Text = 'Method';
            app.MethodDropDown = uidropdown(app.GridLayout4);
            app.MethodDropDown.Items = {'Unique String', 'Numeric', 'NumClasses'};
            app.MethodDropDown.ValueChangedFcn = createCallbackFcn(app, @MethodDropDownValueChanged, true);
            app.MethodDropDown.Value = 'Unique String';
            app.VariableDropDown = uidropdown(app.GridLayout4);
            app.VariableDropDown.Items = {};
            app.VariableDropDown.ValueChangedFcn = createCallbackFcn(app, @VariableDropDownValueChanged, true);
            app.VariableDropDown.Value = {};
            app.MaskSelectionButton = uibutton(app.GridLayout4, 'push');
            app.MaskSelectionButton.ButtonPushedFcn = createCallbackFcn(app, @MaskSelectionButtonPushed, true);
            app.MaskSelectionButton.Text = ' Mask Selection';
            app.ResetMaskButton = uibutton(app.GridLayout4, 'push');
            app.ResetMaskButton.ButtonPushedFcn = createCallbackFcn(app, @ResetMaskButtonPushed, true);
            app.ResetMaskButton.Text = 'Reset Mask';
            app.SelectAllButton_3 = uibutton(app.GridLayout4, 'push');
            app.SelectAllButton_3.ButtonPushedFcn = createCallbackFcn(app, @SelectAllButton_3Pushed, true);
            app.SelectAllButton_3.Text = 'Select All';
            app.NumClassesPanel = uipanel(app.GridLayout4);
            app.NumClassesPanel.Title = 'Num Classes';
            app.NumClassesPanel.Visible = 'off';
            app.GridLayout3 = uigridlayout(app.NumClassesPanel);
            app.UIAxes2_2 = uiaxes(app.GridLayout3);
            xlabel(app.UIAxes2_2, 'X')
            ylabel(app.UIAxes2_2, 'Y')
            zlabel(app.UIAxes2_2, 'Z')
            app.UIAxes = uiaxes(app.GridLayout3);
            xlabel(app.UIAxes, 'n Classes')
            ylabel(app.UIAxes, 'sumd')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.Visible = 'off';
            app.RandomSeedSpinner = uispinner(app.GridLayout3);
            app.RandomSeedSpinner.Limits = [1 Inf];
            app.RandomSeedSpinner.RoundFractionalValues = 'on';
            app.RandomSeedSpinner.Value = 1;
            app.RandomSeedSpinnerLabel = uilabel(app.GridLayout3);
            app.RandomSeedSpinnerLabel.Text = 'Random Seed';
            app.IterationsSpinner = uispinner(app.GridLayout3);
            app.IterationsSpinner.Limits = [1 Inf];
            app.IterationsSpinner.RoundFractionalValues = 'on';
            app.IterationsSpinner.Value = 5;
            app.IterationsSpinnerLabel = uilabel(app.GridLayout3);
            app.IterationsSpinnerLabel.Text = 'Iterations';
            app.RunClassTestButton = uibutton(app.GridLayout3, 'push');
            app.RunClassTestButton.ButtonPushedFcn = createCallbackFcn(app, @RunClassTestButtonPushed, true);
            app.RunClassTestButton.Text = 'Run Class Test';
            app.Norm_CheckBox = uicheckbox(app.GridLayout3);
            app.Norm_CheckBox.Text = 'Normalize';
            app.Norm_CheckBox.Value = true;
            app.CLR_CheckBox = uicheckbox(app.GridLayout3);
            app.CLR_CheckBox.Text = 'CLR';
            app.MinClassesSpinnerLabel = uilabel(app.GridLayout3);
            app.MinClassesSpinnerLabel.Text = 'Min # Classes';
            app.MinClassesSpinner = uispinner(app.GridLayout3);
            app.MinClassesSpinner.Limits = [2 Inf];
            app.MinClassesSpinner.RoundFractionalValues = 'on';
            app.MinClassesSpinner.Value = 2;
            app.MaxClassesSpinnerLabel = uilabel(app.GridLayout3);
            app.MaxClassesSpinnerLabel.Text = 'Max # Classes';
            app.MaxClassesSpinner = uispinner(app.GridLayout3);
            app.MaxClassesSpinner.Limits = [2 Inf];
            app.MaxClassesSpinner.RoundFractionalValues = 'on';
            app.MaxClassesSpinner.Value = 15;
            app.ClassesSpinnerLabel = uilabel(app.GridLayout3);
            app.ClassesSpinnerLabel.Text = '# Classes';
            app.ClassesSpinner = uispinner(app.GridLayout3);
            app.ClassesSpinner.Limits = [2 Inf];
            app.ClassesSpinner.RoundFractionalValues = 'on';
            app.ClassesSpinner.ValueChangedFcn = createCallbackFcn(app, @ClassesSpinnerValueChanged, true);
            app.ClassesSpinner.Value = 10;
            app.SortClassesDropDownLabel = uilabel(app.GridLayout3);
            app.SortClassesDropDownLabel.Text = 'Sort Classes';
            app.SortClassesDropDown = uidropdown(app.GridLayout3);
            app.SortClassesDropDown.Items = {'no sort', 'C Distance', 'Integration', 'n Pixel'};
            app.SortClassesDropDown.Value = 'Integration';
            app.Add2TableButton = uibutton(app.GridLayout3, 'push');
            app.Add2TableButton.ButtonPushedFcn = createCallbackFcn(app, @Add2TableButtonPushed, true);
            app.Add2TableButton.Text = 'Add2Table';
            app.DeselectAllButton_2 = uibutton(app.GridLayout3, 'push');
            app.DeselectAllButton_2.ButtonPushedFcn = createCallbackFcn(app, @DeselectAllButton_2Pushed, true);
            app.DeselectAllButton_2.Text = 'Deselect All';
            app.SelectAllButton_2 = uibutton(app.GridLayout3, 'push');
            app.SelectAllButton_2.ButtonPushedFcn = createCallbackFcn(app, @SelectAllButton_2Pushed, true);
            app.SelectAllButton_2.Text = 'Select All';
            app.sqrt_CheckBox = uicheckbox(app.GridLayout3);
            app.sqrt_CheckBox.Text = 'Sqrt';
            app.MaskClassButton = uibutton(app.GridLayout3, 'push');
            app.MaskClassButton.ButtonPushedFcn = createCallbackFcn(app, @MaskClassButtonPushed, true);
            app.MaskClassButton.Text = 'Mask Class';
            app.LogCheckBox = uicheckbox(app.GridLayout3);
            app.LogCheckBox.Text = 'Log';
            app.SelectButton_2 = uibutton(app.GridLayout3, 'push');
            app.SelectButton_2.ButtonPushedFcn = createCallbackFcn(app, @SelectButton_2Pushed, true);
            app.SelectButton_2.Text = 'Select';
            app.TabGroup2 = uitabgroup(app.GridLayout3);
            app.VarSelectionTab_2 = uitab(app.TabGroup2);
            app.VarSelectionTab_2.Title = 'VarSelection';
            app.GridLayout8 = uigridlayout(app.VarSelectionTab_2);
            app.Tree_2 = uitree(app.GridLayout8, 'checkbox');
            app.Tree_2.SelectionChangedFcn = createCallbackFcn(app, @Tree_2SelectionChanged, true);
            app.ResultTab = uitab(app.TabGroup2);
            app.ResultTab.Title = 'Result';
            app.GridLayout9 = uigridlayout(app.ResultTab);
            app.ClassTree = uitree(app.GridLayout9, 'checkbox');
            app.ClassTree.Editable = 'on';
            app.UnmaskButton = uibutton(app.GridLayout4, 'push');
            app.UnmaskButton.ButtonPushedFcn = createCallbackFcn(app, @UnmaskButtonPushed, true);
            app.UnmaskButton.Text = 'Unmask';
            app.VarSelectionTab = uitab(app.TabGroup);
            app.VarSelectionTab.Title = 'VarSelection';
            app.GridLayout6 = uigridlayout(app.VarSelectionTab);
            app.VarSelectionHistPlot = uiaxes(app.GridLayout6);
            xlabel(app.VarSelectionHistPlot, 'X')
            ylabel(app.VarSelectionHistPlot, 'Y')
            zlabel(app.VarSelectionHistPlot, 'Z')
            app.VarSelectionTree = uitree(app.GridLayout6, 'checkbox');
            app.VarSelectionTree.SelectionChangedFcn = createCallbackFcn(app, @VarSelectionTreeSelectionChanged, true);
            % Assign Checked Nodes
            app.VarSelectionTree.CheckedNodesChangedFcn = createCallbackFcn(app, @VarSelectionTreeCheckedNodesChanged, true);
            app.SelectAllButton = uibutton(app.GridLayout6, 'push');
            app.SelectAllButton.ButtonPushedFcn = createCallbackFcn(app, @SelectAllButtonPushed, true);
            app.SelectAllButton.Text = 'Select All';
            app.DeselectAllButton = uibutton(app.GridLayout6, 'push');
            app.DeselectAllButton.ButtonPushedFcn = createCallbackFcn(app, @DeselectAllButtonPushed, true);
            app.DeselectAllButton.Text = 'Deselect All';
            app.SelectButton = uibutton(app.GridLayout6, 'push');
            app.SelectButton.ButtonPushedFcn = createCallbackFcn(app, @SelectButtonPushed, true);
            app.SelectButton.Visible = 'off';
            app.SelectButton.Text = 'Select';
            app.DataSelectionTab = uitab(app.TabGroup);
            app.DataSelectionTab.Title = 'Data Selection';
            app.GridLayout5 = uigridlayout(app.DataSelectionTab);
            app.SymbolDropDownLabel = uilabel(app.GridLayout5);
            app.SymbolDropDownLabel.Text = 'Symbol';
            app.SymbolDropDown = uidropdown(app.GridLayout5);
            app.SymbolDropDown.Items = {'Circle', 'Plus sign', 'Asterisk', 'Point', 'Cross', 'Horizontal line', 'Vertical line', 'Square', 'Diamond', 'Upward-pointing triangle', 'Downward-pointing triangle', 'Right-pointing triangle', 'Left-pointing triangle', 'Pentagram', 'Hexagram'};
            app.SymbolDropDown.ItemsData = {'o', '+', '*', '.', 'x', '_', '|', 's', 'd', '^', 'v', '>', '<', 'p', 'h', 'none'};
            app.SymbolDropDown.ValueChangedFcn = createCallbackFcn(app, @SymbolDropDownValueChanged, true);
            app.SymbolDropDown.Value = '.';
            app.PlotDataCheckBox = uicheckbox(app.GridLayout5);
            app.PlotDataCheckBox.ValueChangedFcn = createCallbackFcn(app, @PlotDataCheckBoxValueChanged, true);
            app.PlotDataCheckBox.Text = 'Plot Linear';
            app.PlotLogCheckBox = uicheckbox(app.GridLayout5);
            app.PlotLogCheckBox.ValueChangedFcn = createCallbackFcn(app, @PlotLogCheckBoxValueChanged, true);
            app.PlotLogCheckBox.Text = 'Plot Log';
            app.PlotLogCheckBox.Value = true;
            app.PlotCLRCheckBox = uicheckbox(app.GridLayout5);
            app.PlotCLRCheckBox.ValueChangedFcn = createCallbackFcn(app, @PlotCLRCheckBoxValueChanged, true);
            app.PlotCLRCheckBox.Text = 'Plot CLR';
            app.cmap_table = uitable(app.GridLayout5);
            app.cmap_table.ColumnName = {'Active'; 'ClassNames'; 'Value'; 'rgb'; 'n'};
            app.cmap_table.RowName = {};
            app.cmap_table.ColumnSortable = [true true false false true];
            app.cmap_table.ColumnEditable = [true true false false false];
            app.cmap_table.CellEditCallback = createCallbackFcn(app, @cmap_tableCellEdit, true);
            app.cmap_table.ButtonDownFcn = createCallbackFcn(app, @cmap_tableButtonDown, true);
            app.CorPlotButton = uibutton(app.GridLayout5, 'push');
            app.CorPlotButton.ButtonPushedFcn = createCallbackFcn(app, @CorPlotButtonPushed, true);
            app.CorPlotButton.Text = 'CorPlot';
            app.ColormapDropDownLabel = uilabel(app.GridLayout5);
            app.ColormapDropDownLabel.Text = 'Colormap';
            app.Options_ColormapDropDown = uidropdown(app.GridLayout5);
            app.Options_ColormapDropDown.Items = {'parula', 'turbo', 'hsv', 'hot', 'cool', 'spring', 'summer', 'autumn', 'winter', 'gray', 'bone', 'copper', 'pink', 'jet', 'lines', 'colorcube', 'prism', 'flag', 'white', 'ABC', 'custom'};
            app.Options_ColormapDropDown.ValueChangedFcn = createCallbackFcn(app, @Options_ColormapDropDownValueChanged, true);
            app.Options_ColormapDropDown.Value = 'parula';
            app.ColormapPreviewCheckBox = uicheckbox(app.GridLayout5);
            app.ColormapPreviewCheckBox.ValueChangedFcn = createCallbackFcn(app, @ColormapPreviewCheckBoxValueChanged, true);
            app.ColormapPreviewCheckBox.Text = 'Colormap Preview';
            app.StoreDataButton = uibutton(app.GridLayout5, 'push');
            app.StoreDataButton.ButtonPushedFcn = createCallbackFcn(app, @StoreDataButtonPushed, true);
            app.StoreDataButton.Text = 'Store Data';
            app.StoreClassMeanButton = uibutton(app.GridLayout5, 'push');
            app.StoreClassMeanButton.ButtonPushedFcn = createCallbackFcn(app, @StoreClassMeanButtonPushed, true);
            app.StoreClassMeanButton.Text = 'Store Class Mean';
            app.StoreClassMedianButton = uibutton(app.GridLayout5, 'push');
            app.StoreClassMedianButton.ButtonPushedFcn = createCallbackFcn(app, @StoreClassMedianButtonPushed, true);
            app.StoreClassMedianButton.Text = 'Store Class Median';
            app.InvertColormapCheckBox = uicheckbox(app.GridLayout5);
            app.InvertColormapCheckBox.ValueChangedFcn = createCallbackFcn(app, @ColormapPreviewCheckBoxValueChanged, true);
            app.InvertColormapCheckBox.Text = 'Invert Colormap';
            app.MarkerSizeEditField = uieditfield(app.GridLayout5, 'numeric');
            app.MarkerSizeEditField.Value = 1;
            app.PlotsqrtCheckBox = uicheckbox(app.GridLayout5);
            app.PlotsqrtCheckBox.ValueChangedFcn = createCallbackFcn(app, @PlotsqrtCheckBoxValueChanged, true);
            app.PlotsqrtCheckBox.Text = 'Plot Sqrt';
            app.PlotsTab = uitab(app.TabGroup);
            app.PlotsTab.Title = 'Plots';
            app.GridLayout7 = uigridlayout(app.PlotsTab);
            app.MultiCor_table = uitable(app.GridLayout7);
            app.MultiCor_table.ColumnName = {'Active'; 'Group'; 'Name'; 'Symbol'; 'SymbolSize'; 'rgb'; 'n'};
            app.MultiCor_table.RowName = {};
            app.MultiCor_table.ColumnSortable = [true true true true true false true];
            app.MultiCor_table.ColumnEditable = [true false true true true false false];
            app.MultiCor_table.CellEditCallback = createCallbackFcn(app, @MultiCor_tableCellEdit, true);
            app.MultiCor_table.ButtonDownFcn = createCallbackFcn(app, @MultiCor_tableButtonDown, true);
            app.Tree_MultiCor_VarName = uitree(app.GridLayout7, 'checkbox');
            app.Tree_MultiCor_Groups = uitree(app.GridLayout7, 'checkbox');
            % Assign Checked Nodes
            app.Tree_MultiCor_Groups.CheckedNodesChangedFcn = createCallbackFcn(app, @Tree_MultiCor_GroupsCheckedNodesChanged, true);
            app.RemoveGroupButton = uibutton(app.GridLayout7, 'push');
            app.RemoveGroupButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveGroupButtonPushed, true);
            app.RemoveGroupButton.Text = 'Remove Group';
            app.ClearAllButton = uibutton(app.GridLayout7, 'push');
            app.ClearAllButton.ButtonPushedFcn = createCallbackFcn(app, @ClearAllButtonPushed, true);
            app.ClearAllButton.Text = 'Clear All';
            app.moveupButton = uibutton(app.GridLayout7, 'push');
            app.moveupButton.ButtonPushedFcn = createCallbackFcn(app, @moveupButtonPushed, true);
            app.moveupButton.Text = 'move up';
            app.movedownButton = uibutton(app.GridLayout7, 'push');
            app.movedownButton.ButtonPushedFcn = createCallbackFcn(app, @movedownButtonPushed, true);
            app.movedownButton.Text = 'move down';
            app.ActiveSetSpinnerLabel = uilabel(app.GridLayout7);
            app.ActiveSetSpinnerLabel.Visible = 'off';
            app.ActiveSetSpinnerLabel.Text = 'Active Set';
            app.ActiveSetSpinner = uispinner(app.GridLayout7);
            app.ActiveSetSpinner.RoundFractionalValues = 'on';
            app.ActiveSetSpinner.ValueChangedFcn = createCallbackFcn(app, @ActiveSetSpinnerValueChanged, true);
            app.ActiveSetSpinner.Visible = 'off';
            app.ColorPreviewCheckBox = uicheckbox(app.GridLayout7);
            app.ColorPreviewCheckBox.ValueChangedFcn = createCallbackFcn(app, @ColorPreviewCheckBoxValueChanged, true);
            app.ColorPreviewCheckBox.Text = 'Color Preview';
            app.TabGroup3 = uitabgroup(app.GridLayout7);
            app.ScatterPlotTab = uitab(app.TabGroup3);
            app.ScatterPlotTab.Title = 'ScatterPlot';
            app.GridLayout10 = uigridlayout(app.ScatterPlotTab);
            app.NewCorrelationPlotButton = uibutton(app.GridLayout10, 'push');
            app.NewCorrelationPlotButton.ButtonPushedFcn = createCallbackFcn(app, @NewCorrelationPlotButtonPushed, true);
            app.NewCorrelationPlotButton.Text = 'New Correlation Plot';
            app.PlotsqrtCheckBox_2 = uicheckbox(app.GridLayout10);
            app.PlotsqrtCheckBox_2.ValueChangedFcn = createCallbackFcn(app, @PlotsqrtCheckBox_2ValueChanged, true);
            app.PlotsqrtCheckBox_2.Text = 'Plot Sqrt';
            app.ExcludeEmptyCheckBox = uicheckbox(app.GridLayout10);
            app.ExcludeEmptyCheckBox.Text = 'Exclude Empty';
            app.ExcludeEmptyCheckBox.Value = true;
            app.LinkAxesCheckBox = uicheckbox(app.GridLayout10);
            app.LinkAxesCheckBox.Text = 'Link Axes';
            app.PlotCLRCheckBox_2 = uicheckbox(app.GridLayout10);
            app.PlotCLRCheckBox_2.ValueChangedFcn = createCallbackFcn(app, @PlotCLRCheckBox_2ValueChanged, true);
            app.PlotCLRCheckBox_2.Text = 'Plot CLR';
            app.PlotLogCheckBox_2 = uicheckbox(app.GridLayout10);
            app.PlotLogCheckBox_2.ValueChangedFcn = createCallbackFcn(app, @PlotLogCheckBox_2ValueChanged, true);
            app.PlotLogCheckBox_2.Text = 'Plot Log';
            app.PlotLogCheckBox_2.Value = true;
            app.PlotDataCheckBox_2 = uicheckbox(app.GridLayout10);
            app.PlotDataCheckBox_2.ValueChangedFcn = createCallbackFcn(app, @PlotDataCheckBox_2ValueChanged, true);
            app.PlotDataCheckBox_2.Text = 'Plot Data';
            app.GapDropDownLabel = uilabel(app.GridLayout10);
            app.GapDropDownLabel.Text = 'Gap';
            app.GapDropDown = uidropdown(app.GridLayout10);
            app.GapDropDown.Items = {'loose', 'compact', 'tight', 'none'};
            app.GapDropDown.Value = 'compact';
            app.TernPlotTab = uitab(app.TabGroup3);
            app.TernPlotTab.Title = 'TernPlot';
            app.GridLayout11 = uigridlayout(app.TernPlotTab);
            app.NewTernPlotButton = uibutton(app.GridLayout11, 'push');
            app.NewTernPlotButton.ButtonPushedFcn = createCallbackFcn(app, @NewTernPlotButtonPushed, true);
            app.NewTernPlotButton.Text = 'New Tern Plot';
            app.PlotLogCheckBox_3 = uicheckbox(app.GridLayout11);
            app.PlotLogCheckBox_3.ValueChangedFcn = createCallbackFcn(app, @PlotLogCheckBox_3ValueChanged, true);
            app.PlotLogCheckBox_3.Text = 'Plot Log';
            app.PlotLogCheckBox_3.Value = true;
            app.PlotDataCheckBox_3 = uicheckbox(app.GridLayout11);
            app.PlotDataCheckBox_3.ValueChangedFcn = createCallbackFcn(app, @PlotDataCheckBox_3ValueChanged, true);
            app.PlotDataCheckBox_3.Text = 'Plot Data';
            app.MaxSubpltSpinnerLabel = uilabel(app.GridLayout11);
            app.MaxSubpltSpinnerLabel.Text = 'Max Subplt';
            app.MaxSubpltSpinner = uispinner(app.GridLayout11);
            app.MaxSubpltSpinner.Limits = [1 100];
            app.MaxSubpltSpinner.RoundFractionalValues = 'on';
            app.MaxSubpltSpinner.Value = 16;
            app.PlotsqrtCheckBox_3 = uicheckbox(app.GridLayout11);
            app.PlotsqrtCheckBox_3.ValueChangedFcn = createCallbackFcn(app, @PlotsqrtCheckBox_3ValueChanged, true);
            app.PlotsqrtCheckBox_3.Text = 'Plot Sqrt';
            app.PlotCLRCheckBox_3 = uicheckbox(app.GridLayout11);
            app.PlotCLRCheckBox_3.ValueChangedFcn = createCallbackFcn(app, @PlotCLRCheckBox_3ValueChanged, true);
            app.PlotCLRCheckBox_3.Text = 'Plot CLR';
            app.Norm_CheckBox_2 = uicheckbox(app.GridLayout11);
            app.Norm_CheckBox_2.Text = 'Normalize';
            app.Panel = uipanel(app.GeoCPlotUIFigure);
            app.Panel.Title = 'Panel';
            app.Tree2 = uitree(app.Panel, 'checkbox');
            app.xDropDownLabel = uilabel(app.Panel);
            app.xDropDownLabel.Text = 'x';
            app.xDropDown = uidropdown(app.Panel);
            app.yDropDownLabel = uilabel(app.Panel);
            app.yDropDownLabel.Text = 'y';
            app.yDropDown = uidropdown(app.Panel);
            app.CloseButton = uibutton(app.Panel, 'push');
            app.CloseButton.Text = 'Close';
            app.NumericPanel = uipanel(app.GeoCPlotUIFigure);
            app.NumericPanel.Title = 'Numeric';
            app.GridLayout12 = uigridlayout(app.NumericPanel);
            app.FilterButton = uibutton(app.GridLayout12, 'push');
            app.FilterButton.Text = 'Filter';
            app.ExcludeInfButton = uibutton(app.GridLayout12, 'push');
            app.ExcludeInfButton.Text = 'Exclude Inf';
            app.RangeMinEditField = uieditfield(app.GridLayout12, 'numeric');
            app.RangeMaxEditField = uieditfield(app.GridLayout12, 'numeric');
            app.ExcludeNaNButton_2 = uibutton(app.GridLayout12, 'push');
            app.ExcludeNaNButton_2.Text = 'Exclude NaN';
            app.ExcludenegativeButton = uibutton(app.GridLayout12, 'push');
            app.ExcludenegativeButton.Text = 'Exclude negative';
            app.AllnumericColumnsDropDownLabel = uilabel(app.GridLayout12);
            app.AllnumericColumnsDropDownLabel.Text = 'All numeric Columns';
            app.AllnumericColumnsDropDown = uidropdown(app.GridLayout12);
            % Show the figure after all components are created
            app.GeoCPlotUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GeoCPlot(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.GeoCPlotUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.GeoCPlotUIFigure)
        end
    end
end