classdef VisModule < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        VisModule_GUI                   matlab.ui.Figure
        GridLayout2                     matlab.ui.container.GridLayout
        TabGroup                        matlab.ui.container.TabGroup
        PlotTab                         matlab.ui.container.Tab
        GridLayout3                     matlab.ui.container.GridLayout
        MinPlotXLogo                    matlab.ui.control.Image
        Hyperlink                       matlab.ui.control.Hyperlink
        TabGroup2                       matlab.ui.container.TabGroup
        ProfilePlotTab                  matlab.ui.container.Tab
        GridLayout5                     matlab.ui.container.GridLayout
        CustomYLabelEditField           matlab.ui.control.EditField
        CustomYLabelEditFieldLabel      matlab.ui.control.Label
        CustomXLabelEditField           matlab.ui.control.EditField
        CustomXLabelEditFieldLabel      matlab.ui.control.Label
        LabelInterpreterDropDown        matlab.ui.control.DropDown
        LabelInterpreterDropDownLabel   matlab.ui.control.Label
        CustomAxesLabelsCheckBox        matlab.ui.control.CheckBox
        IndividualEasyPlotButton        matlab.ui.control.Button
        AddToEasyPlotButton             matlab.ui.control.Button
        NewEasyPlotButton               matlab.ui.control.Button
        VarSelectionTree                matlab.ui.container.CheckBoxTree
        FigureFontSizeSpinner           matlab.ui.control.Spinner
        FigureFontSizeSpinnerLabel      matlab.ui.control.Label
        FilterasNaNCheckBox             matlab.ui.control.CheckBox
        XAxesVariableDropDown           matlab.ui.control.DropDown
        XAxesVariableDropDownLabel      matlab.ui.control.Label
        DisplayFilteredCheckBox         matlab.ui.control.CheckBox
        HistogramFilterButton           matlab.ui.control.Button
        LayoutDropDown                  matlab.ui.control.DropDown
        LayoutDropDownLabel             matlab.ui.control.Label
        RowHightSpinner                 matlab.ui.control.Spinner
        RowHightLabel                   matlab.ui.control.Label
        NumberofColumnsSpinner          matlab.ui.control.Spinner
        NumberofColumnsSpinnerLabel     matlab.ui.control.Label
        LineWidthSpinner                matlab.ui.control.Spinner
        LineWidthSpinnerLabel           matlab.ui.control.Label
        MarkerSizeSpinner               matlab.ui.control.Spinner
        MarkerSizeSpinnerLabel          matlab.ui.control.Label
        MarkerEdgeColorDropDown         matlab.ui.control.DropDown
        ColorDropDownLabel_3            matlab.ui.control.Label
        SymbolDropDown                  matlab.ui.control.DropDown
        LineStyleDropDown               matlab.ui.control.DropDown
        LineStyleDropDownLabel          matlab.ui.control.Label
        SymbolDropDownLabel             matlab.ui.control.Label
        LineColorDropDown               matlab.ui.control.DropDown
        ColorDropDownLabel_2            matlab.ui.control.Label
        MarkerFaceColorDropDown         matlab.ui.control.DropDown
        ColorDropDownLabel              matlab.ui.control.Label
        Panel                           matlab.ui.container.Panel
        DataSelectionTab                matlab.ui.container.Tab
        GridLayout4                     matlab.ui.container.GridLayout
        HistogramFilterButton_2         matlab.ui.control.Button
        ExcludeEmptyCheckBox            matlab.ui.control.CheckBox
        UnmaskButton                    matlab.ui.control.Button
        SelectAllButton                 matlab.ui.control.Button
        ResetMaskButton                 matlab.ui.control.Button
        MaskSelectionButton             matlab.ui.control.Button
        VariableDropDown                matlab.ui.control.DropDown
        MethodDropDown                  matlab.ui.control.DropDown
        MethodDropDownLabel             matlab.ui.control.Label
        ClassTable                      matlab.ui.control.Table
        DataSubdivisionTab              matlab.ui.container.Tab
        GridLayout6                     matlab.ui.container.GridLayout
        CombineColumnsButton            matlab.ui.control.Button
        SplitMatrixInclusionButton      matlab.ui.control.Button
        GeoCPlotButton                  matlab.ui.control.Button
        AppearanceDropDown              matlab.ui.control.DropDown
        AppearanceDropDownLabel         matlab.ui.control.Label
        nEditField                      matlab.ui.control.NumericEditField
        nEditFieldLabel                 matlab.ui.control.Label
        ListBox_2                       matlab.ui.control.ListBox
        ListBox                         matlab.ui.control.ListBox
        UseDataSubdivisionCheckBox      matlab.ui.control.CheckBox
        ResetButton                     matlab.ui.control.Button
        CustomPlotsTab                  matlab.ui.container.Tab
        GridLayout8                     matlab.ui.container.GridLayout
        FLabelEditField                 matlab.ui.control.EditField
        FLabelEditFieldLabel            matlab.ui.control.Label
        ELabelEditField                 matlab.ui.control.EditField
        ELabelEditFieldLabel            matlab.ui.control.Label
        FAxesVariableDropDown           matlab.ui.control.DropDown
        FAxesVariableDropDownLabel      matlab.ui.control.Label
        EAxesVariableDropDown           matlab.ui.control.DropDown
        EAxesVariableDropDownLabel      matlab.ui.control.Label
        ScaleDropDown                   matlab.ui.control.DropDown
        ScaleDropDownLabel              matlab.ui.control.Label
        AnnotationListBox               matlab.ui.control.ListBox
        AnnotationCheckBox              matlab.ui.control.CheckBox
        CScaleDropDown                  matlab.ui.control.DropDown
        CScaleDropDownLabel             matlab.ui.control.Label
        ZScaleDropDown                  matlab.ui.control.DropDown
        ZScaleDropDownLabel             matlab.ui.control.Label
        YScaleDropDownLabel             matlab.ui.control.Label
        YScaleDropDown                  matlab.ui.control.DropDown
        XScaleDropDown                  matlab.ui.control.DropDown
        XScaleDropDownLabel             matlab.ui.control.Label
        FigureFontSizeSpinner_3         matlab.ui.control.Spinner
        FigureFontSizeSpinner_3Label    matlab.ui.control.Label
        LabelInterpreterDropDown_2      matlab.ui.control.DropDown
        LabelInterpreterDropDown_2Label  matlab.ui.control.Label
        DAxesVariableDropDown           matlab.ui.control.DropDown
        DAxesVariableDropDownLabel      matlab.ui.control.Label
        DLabelEditField                 matlab.ui.control.EditField
        DLabelEditFieldLabel            matlab.ui.control.Label
        PlotType_DropDown               matlab.ui.control.DropDown
        PlotTypeLabel                   matlab.ui.control.Label
        ColorbarLabelEditField          matlab.ui.control.EditField
        ColorbarLabelEditFieldLabel     matlab.ui.control.Label
        ZLabelEditField                 matlab.ui.control.EditField
        ZLabelEditFieldLabel            matlab.ui.control.Label
        YLabelEditField                 matlab.ui.control.EditField
        YLabelEditFieldLabel            matlab.ui.control.Label
        XLabelEditField                 matlab.ui.control.EditField
        XLabelEditFieldLabel            matlab.ui.control.Label
        CAxesVariableDropDown           matlab.ui.control.DropDown
        CAxesVariableDropDownLabel      matlab.ui.control.Label
        ZAxesVariableDropDown           matlab.ui.control.DropDown
        ZAxesVariableDropDownLabel      matlab.ui.control.Label
        YAxesVariableDropDown           matlab.ui.control.DropDown
        YAxesVariableDropDownLabel      matlab.ui.control.Label
        XAxesVariableDropDown_2         matlab.ui.control.DropDown
        XAxesVariableDropDown_2Label    matlab.ui.control.Label
        IndividualXYEasyPlotButton      matlab.ui.control.Button
        CustomColormapCheckBox          matlab.ui.control.CheckBox
        Panel_2                         matlab.ui.container.Panel
        GridLayout                      matlab.ui.container.GridLayout
        Options_LowerColor              matlab.ui.control.DropDown
        Options_InvertColormap          matlab.ui.control.CheckBox
        Options_ColormapDropDown        matlab.ui.control.DropDown
        ColormapDropDownLabel           matlab.ui.control.Label
        Options_LogColormap             matlab.ui.control.CheckBox
        Options_ColormapResEditField    matlab.ui.control.NumericEditField
        Options_UpperColor              matlab.ui.control.DropDown
        Options_UpperCheckBox           matlab.ui.control.CheckBox
        Options_LowerCheckBox           matlab.ui.control.CheckBox
        DisplayValuesOutsideData        matlab.ui.control.CheckBox
        NewXYEasyPlotButton_4           matlab.ui.control.Button
        TraceElementsTab                matlab.ui.container.Tab
        GridLayout14                    matlab.ui.container.GridLayout
        TraceElementPlotPanel           matlab.ui.container.Panel
        GridLayout11                    matlab.ui.container.GridLayout
        DropDown_2                      matlab.ui.control.DropDown
        PlotDetectionLimitCheckBox      matlab.ui.control.CheckBox
        DefaultsDropDown                matlab.ui.control.DropDown
        DefaultsDropDownLabel           matlab.ui.control.Label
        TraceElementPlotButton          matlab.ui.control.Button
        ElementstoPlotListBox           matlab.ui.control.ListBox
        UserNormPanel                   matlab.ui.container.Panel
        GridLayout10                    matlab.ui.container.GridLayout
        UserNormCheckBox                matlab.ui.control.CheckBox
        SigDigitsSpinner                matlab.ui.control.Spinner
        SigDigitsSpinnerLabel           matlab.ui.control.Label
        UserNormTraceElementPlotButton  matlab.ui.control.Button
        SaveNormDataButton              matlab.ui.control.Button
        ListBox_4                       matlab.ui.control.ListBox
        ListBox_3                       matlab.ui.control.ListBox
        NormalisedDiagramsPanel         matlab.ui.container.Panel
        GridLayout12                    matlab.ui.container.GridLayout
        NormalisetoDropDown             matlab.ui.control.DropDown
        NormalisetoDropDownLabel        matlab.ui.control.Label
        UpdateNormListButton            matlab.ui.control.Button
        NormalisedTEPlotButton          matlab.ui.control.Button
        SettingsPanel                   matlab.ui.container.Panel
        GridLayout13                    matlab.ui.container.GridLayout
        FigureFontSizeSpinner_4         matlab.ui.control.Spinner
        FigureFontSizeSpinner_4Label    matlab.ui.control.Label
        FilterElementsCheckBox          matlab.ui.control.CheckBox
        AnnotationListBox_TE            matlab.ui.control.ListBox
        AnnotationCheckBox_2            matlab.ui.control.CheckBox
        DiagramsTab                     matlab.ui.container.Tab
        DiagramTreeDropdown             matlab.ui.control.DropDown
        DropDown_3Label                 matlab.ui.control.Label
        ReloadButton                    matlab.ui.control.Button
        TextArea                        matlab.ui.control.TextArea
        Button                          matlab.ui.control.Button
        DiagramTree                     matlab.ui.container.CheckBoxTree
        UIAxes                          matlab.ui.control.UIAxes
        ExportButton_2                  matlab.ui.control.Button
        DropDown                        matlab.ui.control.DropDown
        UpdateButton                    matlab.ui.control.Button
        Tree_2                          matlab.ui.container.CheckBoxTree
        Tree                            matlab.ui.container.CheckBoxTree
        FileInfoNode                    matlab.ui.container.TreeNode
        EPMA_Node                       matlab.ui.container.TreeNode
        MSNode                          matlab.ui.container.TreeNode
        BulkNode                        matlab.ui.container.TreeNode
        MetadataNode                    matlab.ui.container.TreeNode
        apfuNode                        matlab.ui.container.TreeNode
        StructuralFormularNode          matlab.ui.container.TreeNode
        CalcOutNode                     matlab.ui.container.TreeNode
        OptionsNode                     matlab.ui.container.TreeNode
        DataTable_2                     matlab.ui.container.Tab
        GridLayout7                     matlab.ui.container.GridLayout
        DataCalculatorModuleButton      matlab.ui.control.Button
        LoadTableButton                 matlab.ui.control.Button
        RemoveFromMask                  matlab.ui.control.Button
        Add2Mask                        matlab.ui.control.Button
        InvertMask                      matlab.ui.control.Button
        ResetMask                       matlab.ui.control.Button
        SpotVisModuleButton             matlab.ui.control.Button
        UITable                         matlab.ui.control.Table
        ContextMenu                     matlab.ui.container.ContextMenu
        CheckallMenu                    matlab.ui.container.Menu
        UncheckallMenu                  matlab.ui.container.Menu
    end


    properties (Access = private)
        % Description
        GridLayout_Plots % Description
        ColorMapValues
        ColorMaps % Description
        SpiderData
        MassList = {'H1','H2','H3','H4','H5','H6','H7','He2','He3','He4','He5','He6','He7','He8',...
            'He9','He10','Li3','Li4','Li5','Li6','Li7','Li8','Li9','Li10','Li11','Li12',...
            'Be5','Be6','Be7','Be8','Be9','Be10','Be11','Be12','Be13','Be14','Be15','Be16',...
            'B6','B7','B8','B9','B10','B11','B12','B13','B14','B15','B16','B17','B18','B19',...
            'C8','C9','C10','C11','C12','C13','C14','C15','C16','C17','C18','C19','C20','C21','C22',...
            'N10','N11','N12','N13','N14','N15','N16','N17','N18','N19','N20','N21','N22','N23','N24','N25',...
            'O12','O13','O14','O15','O16','O17','O18','O19','O20','O21','O22','O23','O24','O25','O26','O27','O28',...
            'F14','F15','F16','F17','F18','F19','F20','F21','F22','F23','F24','F25','F26','F27','F28','F29','F30','F31',...
            'Ne16','Ne17','Ne18','Ne19','Ne20','Ne21','Ne22','Ne23','Ne24','Ne25','Ne26','Ne27','Ne28','Ne29','Ne30','Ne31','Ne32','Ne33','Ne34',...
            'Na18','Na19','Na20','Na21','Na22','Na23','Na24','Na25','Na26','Na27','Na28','Na29','Na30','Na31','Na32','Na33','Na34','Na35','Na36','Na37',...
            'Mg19','Mg20','Mg21','Mg22','Mg23','Mg24','Mg25','Mg26','Mg27','Mg28','Mg29','Mg30','Mg31','Mg32','Mg33','Mg34','Mg35','Mg36','Mg37','Mg38','Mg39','Mg40',...
            'Al21','Al22','Al23','Al24','Al25','Al26','Al27','Al28','Al29','Al30','Al31','Al32','Al33','Al34','Al35','Al36','Al37','Al38','Al39','Al40','Al41','Al42',...
            'Si22','Si23','Si24','Si25','Si26','Si27','Si28','Si29','Si30','Si31','Si32','Si33','Si34','Si35','Si36','Si37','Si38','Si39','Si40','Si41','Si42','Si43','Si44',...
            'P24','P25','P26','P27','P28','P29','P30','P31','P32','P33','P34','P35','P36','P37','P38','P39','P40','P41','P42','P43','P44','P45','P46',...
            'S26','S27','S28','S29','S30','S31','S32','S33','S34','S35','S36','S37','S38','S39','S40','S41','S42','S43','S44','S45','S46','S47','S48','S49',...
            'Cl28','Cl29','Cl30','Cl31','Cl32','Cl33','Cl34','Cl35','Cl36','Cl37','Cl38','Cl39','Cl40','Cl41','Cl42','Cl43','Cl44','Cl45','Cl46','Cl47','Cl48','Cl49','Cl50','Cl51',...
            'Ar30','Ar31','Ar32','Ar33','Ar34','Ar35','Ar36','Ar37','Ar38','Ar39','Ar40','Ar41','Ar42','Ar43','Ar44','Ar45','Ar46','Ar47','Ar48','Ar49','Ar50','Ar51','Ar52','Ar53',...
            'K32','K33','K34','K35','K36','K37','K38','K39','K40','K41','K42','K43','K44','K45','K46','K47','K48','K49','K50','K51','K52','K53','K54','K55',...
            'Ca34','Ca35','Ca36','Ca37','Ca38','Ca39','Ca40','Ca41','Ca42','Ca43','Ca44','Ca45','Ca46','Ca47','Ca48','Ca49','Ca50','Ca51','Ca52','Ca53','Ca54','Ca55','Ca56','Ca57',...
            'Sc36','Sc37','Sc38','Sc39','Sc40','Sc41','Sc42','Sc43','Sc44','Sc45','Sc46','Sc47','Sc48','Sc49','Sc50','Sc51','Sc52','Sc53','Sc54','Sc55','Sc56','Sc57','Sc58','Sc59','Sc60',...
            'Ti38','Ti39','Ti40','Ti41','Ti42','Ti43','Ti44','Ti45','Ti46','Ti47','Ti48','Ti49','Ti50','Ti51','Ti52','Ti53','Ti54','Ti55','Ti56','Ti57','Ti58','Ti59','Ti60','Ti61','Ti62','Ti63',...
            'V40','V41','V42','V43','V44','V45','V46','V47','V48','V49','V50','V51','V52','V53','V54','V55','V56','V57','V58','V59','V60','V61','V62','V63','V64','V65',...
            'Cr42','Cr43','Cr44','Cr45','Cr46','Cr47','Cr48','Cr49','Cr50','Cr51','Cr52','Cr53','Cr54','Cr55','Cr56','Cr57','Cr58','Cr59','Cr60','Cr61','Cr62','Cr63','Cr64','Cr65','Cr66','Cr67',...
            'Mn44','Mn45','Mn46','Mn47','Mn48','Mn49','Mn50','Mn51','Mn52','Mn53','Mn54','Mn55','Mn56','Mn57','Mn58','Mn59','Mn60','Mn61','Mn62','Mn63','Mn64','Mn65','Mn66','Mn67','Mn68','Mn69',...
            'Fe45','Fe46','Fe47','Fe48','Fe49','Fe50','Fe51','Fe52','Fe53','Fe54','Fe55','Fe56','Fe57','Fe58','Fe59','Fe60','Fe61','Fe62','Fe63','Fe64','Fe65','Fe66','Fe67','Fe68','Fe69','Fe70','Fe71','Fe72',...
            'Co47','Co48','Co49','Co50','Co51','Co52','Co53','Co54','Co55','Co56','Co57','Co58','Co59','Co60','Co61','Co62','Co63','Co64','Co65','Co66','Co67','Co68','Co69','Co70','Co71','Co72','Co73','Co74','Co75',...
            'Ni48','Ni49','Ni50','Ni51','Ni52','Ni53','Ni54','Ni55','Ni56','Ni57','Ni58','Ni59','Ni60','Ni61','Ni62','Ni63','Ni64','Ni65','Ni66','Ni67','Ni68','Ni69','Ni70','Ni71','Ni72','Ni73','Ni74','Ni75','Ni76','Ni77','Ni78',...
            'Cu52','Cu53','Cu54','Cu55','Cu56','Cu57','Cu58','Cu59','Cu60','Cu61','Cu62','Cu63','Cu64','Cu65','Cu66','Cu67','Cu68','Cu69','Cu70','Cu71','Cu72','Cu73','Cu74','Cu75','Cu76','Cu77','Cu78','Cu79','Cu80',...
            'Zn54','Zn55','Zn56','Zn57','Zn58','Zn59','Zn60','Zn61','Zn62','Zn63','Zn64','Zn65','Zn66','Zn67','Zn68','Zn69','Zn70','Zn71','Zn72','Zn73','Zn74','Zn75','Zn76','Zn77','Zn78','Zn79','Zn80','Zn81','Zn82','Zn83',...
            'Ga56','Ga57','Ga58','Ga59','Ga60','Ga61','Ga62','Ga63','Ga64','Ga65','Ga66','Ga67','Ga68','Ga69','Ga70','Ga71','Ga72','Ga73','Ga74','Ga75','Ga76','Ga77','Ga78','Ga79','Ga80','Ga81','Ga82','Ga83','Ga84','Ga85','Ga86',...
            'Ge58','Ge59','Ge60','Ge61','Ge62','Ge63','Ge64','Ge65','Ge66','Ge67','Ge68','Ge69','Ge70','Ge71','Ge72','Ge73','Ge74','Ge75','Ge76','Ge77','Ge78','Ge79','Ge80','Ge81','Ge82','Ge83','Ge84','Ge85','Ge86','Ge87','Ge88','Ge89',...
            'As60','As61','As62','As63','As64','As65','As66','As67','As68','As69','As70','As71','As72','As73','As74','As75','As76','As77','As78','As79','As80','As81','As82','As83','As84','As85','As86','As87','As88','As89','As90','As91','As92',...
            'Se65','Se66','Se67','Se68','Se69','Se70','Se71','Se72','Se73','Se74','Se75','Se76','Se77','Se78','Se79','Se80','Se81','Se82','Se83','Se84','Se85','Se86','Se87','Se88','Se89','Se90','Se91','Se92','Se93','Se94',...
            'Br67','Br68','Br69','Br70','Br71','Br72','Br73','Br74','Br75','Br76','Br77','Br78','Br79','Br80','Br81','Br82','Br83','Br84','Br85','Br86','Br87','Br88','Br89','Br90','Br91','Br92','Br93','Br94','Br95','Br96','Br97',...
            'Kr69','Kr70','Kr71','Kr72','Kr73','Kr74','Kr75','Kr76','Kr77','Kr78','Kr79','Kr80','Kr81','Kr82','Kr83','Kr84','Kr85','Kr86','Kr87','Kr88','Kr89','Kr90','Kr91','Kr92','Kr93','Kr94','Kr95','Kr96','Kr97','Kr98','Kr99','Kr100',...
            'Rb71','Rb72','Rb73','Rb74','Rb75','Rb76','Rb77','Rb78','Rb79','Rb80','Rb81','Rb82','Rb83','Rb84','Rb85','Rb86','Rb87','Rb88','Rb89','Rb90','Rb91','Rb92','Rb93','Rb94','Rb95','Rb96','Rb97','Rb98','Rb99','Rb100','Rb101','Rb102',...
            'Sr73','Sr74','Sr75','Sr76','Sr77','Sr78','Sr79','Sr80','Sr81','Sr82','Sr83','Sr84','Sr85','Sr86','Sr87','Sr88','Sr89','Sr90','Sr91','Sr92','Sr93','Sr94','Sr95','Sr96','Sr97','Sr98','Sr99','Sr100','Sr101','Sr102','Sr103','Sr104','Sr105',...
            'Y76','Y77','Y78','Y79','Y80','Y81','Y82','Y83','Y84','Y85','Y86','Y87','Y88','Y89','Y90','Y91','Y92','Y93','Y94','Y95','Y96','Y97','Y98','Y99','Y100','Y101','Y102','Y103','Y104','Y105','Y106','Y107','Y108',...
            'Zr78','Zr79','Zr80','Zr81','Zr82','Zr83','Zr84','Zr85','Zr86','Zr87','Zr88','Zr89','Zr90','Zr91','Zr92','Zr93','Zr94','Zr95','Zr96','Zr97','Zr98','Zr99','Zr100','Zr101','Zr102','Zr103','Zr104','Zr105','Zr106','Zr107','Zr108','Zr109','Zr110',...
            'Nb81','Nb82','Nb83','Nb84','Nb85','Nb86','Nb87','Nb88','Nb89','Nb90','Nb91','Nb92','Nb93','Nb94','Nb95','Nb96','Nb97','Nb98','Nb99','Nb100','Nb101','Nb102','Nb103','Nb104','Nb105','Nb106','Nb107','Nb108','Nb109','Nb110','Nb111','Nb112','Nb113',...
            'Mo83','Mo84','Mo85','Mo86','Mo87','Mo88','Mo89','Mo90','Mo91','Mo92','Mo93','Mo94','Mo95','Mo96','Mo97','Mo98','Mo99','Mo100','Mo101','Mo102','Mo103','Mo104','Mo105','Mo106','Mo107','Mo108','Mo109','Mo110','Mo111','Mo112','Mo113','Mo114','Mo115',...
            'Tc85','Tc86','Tc87','Tc88','Tc89','Tc90','Tc91','Tc92','Tc93','Tc94','Tc95','Tc96','Tc97','Tc98','Tc99','Tc100','Tc101','Tc102','Tc103','Tc104','Tc105','Tc106','Tc107','Tc108','Tc109','Tc110','Tc111','Tc112','Tc113','Tc114','Tc115','Tc116','Tc117','Tc118',...
            'Ru87','Ru88','Ru89','Ru90','Ru91','Ru92','Ru93','Ru94','Ru95','Ru96','Ru97','Ru98','Ru99','Ru100','Ru101','Ru102','Ru103','Ru104','Ru105','Ru106','Ru107','Ru108','Ru109','Ru110','Ru111','Ru112','Ru113','Ru114','Ru115','Ru116','Ru117','Ru118','Ru119','Ru120',...
            'Rh89','Rh90','Rh91','Rh92','Rh93','Rh94','Rh95','Rh96','Rh97','Rh98','Rh99','Rh100','Rh101','Rh102','Rh103','Rh104','Rh105','Rh106','Rh107','Rh108','Rh109','Rh110','Rh111','Rh112','Rh113','Rh114','Rh115','Rh116','Rh117','Rh118','Rh119','Rh120','Rh121','Rh122',...
            'Pd91','Pd92','Pd93','Pd94','Pd95','Pd96','Pd97','Pd98','Pd99','Pd100','Pd101','Pd102','Pd103','Pd104','Pd105','Pd106','Pd107','Pd108','Pd109','Pd110','Pd111','Pd112','Pd113','Pd114','Pd115','Pd116','Pd117','Pd118','Pd119','Pd120','Pd121','Pd122','Pd123','Pd124',...
            'Ag93','Ag94','Ag95','Ag96','Ag97','Ag98','Ag99','Ag100','Ag101','Ag102','Ag103','Ag104','Ag105','Ag106','Ag107','Ag108','Ag109','Ag110','Ag111','Ag112','Ag113','Ag114','Ag115','Ag116','Ag117','Ag118','Ag119','Ag120','Ag121','Ag122','Ag123','Ag124','Ag125','Ag126','Ag127','Ag128','Ag129','Ag130',...
            'Cd95','Cd96','Cd97','Cd98','Cd99','Cd100','Cd101','Cd102','Cd103','Cd104','Cd105','Cd106','Cd107','Cd108','Cd109','Cd110','Cd111','Cd112','Cd113','Cd114','Cd115','Cd116','Cd117','Cd118','Cd119','Cd120','Cd121','Cd122','Cd123','Cd124','Cd125','Cd126','Cd127','Cd128','Cd129','Cd130','Cd131','Cd132',...
            'In97','In98','In99','In100','In101','In102','In103','In104','In105','In106','In107','In108','In109','In110','In111','In112','In113','In114','In115','In116','In117','In118','In119','In120','In121','In122','In123','In124','In125','In126','In127','In128','In129','In130','In131','In132','In133','In134','In135',...
            'Sn99','Sn100','Sn101','Sn102','Sn103','Sn104','Sn105','Sn106','Sn107','Sn108','Sn109','Sn110','Sn111','Sn112','Sn113','Sn114','Sn115','Sn116','Sn117','Sn118','Sn119','Sn120','Sn121','Sn122','Sn123','Sn124','Sn125','Sn126','Sn127','Sn128','Sn129','Sn130','Sn131','Sn132','Sn133','Sn134','Sn135','Sn136','Sn137',...
            'Sb103','Sb104','Sb105','Sb106','Sb107','Sb108','Sb109','Sb110','Sb111','Sb112','Sb113','Sb114','Sb115','Sb116','Sb117','Sb118','Sb119','Sb120','Sb121','Sb122','Sb123','Sb124','Sb125','Sb126','Sb127','Sb128','Sb129','Sb130','Sb131','Sb132','Sb133','Sb134','Sb135','Sb136','Sb137','Sb138','Sb139',...
            'Te105','Te106','Te107','Te108','Te109','Te110','Te111','Te112','Te113','Te114','Te115','Te116','Te117','Te118','Te119','Te120','Te121','Te122','Te123','Te124','Te125','Te126','Te127','Te128','Te129','Te130','Te131','Te132','Te133','Te134','Te135','Te136','Te137','Te138','Te139','Te140','Te141','Te142',...
            'I108','I109','I110','I111','I112','I113','I114','I115','I116','I117','I118','I119','I120','I121','I122','I123','I124','I125','I126','I127','I128','I129','I130','I131','I132','I133','I134','I135','I136','I137','I138','I139','I140','I141','I142','I143','I144',...
            'Xe110','Xe111','Xe112','Xe113','Xe114','Xe115','Xe116','Xe117','Xe118','Xe119','Xe120','Xe121','Xe122','Xe123','Xe124','Xe125','Xe126','Xe127','Xe128','Xe129','Xe130','Xe131','Xe132','Xe133','Xe134','Xe135','Xe136','Xe137','Xe138','Xe139','Xe140','Xe141','Xe142','Xe143','Xe144','Xe145','Xe146','Xe147',...
            'Cs112','Cs113','Cs114','Cs115','Cs116','Cs117','Cs118','Cs119','Cs120','Cs121','Cs122','Cs123','Cs124','Cs125','Cs126','Cs127','Cs128','Cs129','Cs130','Cs131','Cs132','Cs133','Cs134','Cs135','Cs136','Cs137','Cs138','Cs139','Cs140','Cs141','Cs142','Cs143','Cs144','Cs145','Cs146','Cs147','Cs148','Cs149','Cs150','Cs151',...
            'Ba114','Ba115','Ba116','Ba117','Ba118','Ba119','Ba120','Ba121','Ba122','Ba123','Ba124','Ba125','Ba126','Ba127','Ba128','Ba129','Ba130','Ba131','Ba132','Ba133','Ba134','Ba135','Ba136','Ba137','Ba138','Ba139','Ba140','Ba141','Ba142','Ba143','Ba144','Ba145','Ba146','Ba147','Ba148','Ba149','Ba150','Ba151','Ba152','Ba153',...
            'La117','La118','La119','La120','La121','La122','La123','La124','La125','La126','La127','La128','La129','La130','La131','La132','La133','La134','La135','La136','La137','La138','La139','La140','La141','La142','La143','La144','La145','La146','La147','La148','La149','La150','La151','La152','La153','La154','La155',...
            'Ce119','Ce120','Ce121','Ce122','Ce123','Ce124','Ce125','Ce126','Ce127','Ce128','Ce129','Ce130','Ce131','Ce132','Ce133','Ce134','Ce135','Ce136','Ce137','Ce138','Ce139','Ce140','Ce141','Ce142','Ce143','Ce144','Ce145','Ce146','Ce147','Ce148','Ce149','Ce150','Ce151','Ce152','Ce153','Ce154','Ce155','Ce156','Ce157',...
            'Pr121','Pr122','Pr123','Pr124','Pr125','Pr126','Pr127','Pr128','Pr129','Pr130','Pr131','Pr132','Pr133','Pr134','Pr135','Pr136','Pr137','Pr138','Pr139','Pr140','Pr141','Pr142','Pr143','Pr144','Pr145','Pr146','Pr147','Pr148','Pr149','Pr150','Pr151','Pr152','Pr153','Pr154','Pr155','Pr156','Pr157','Pr158','Pr159',...
            'Nd124','Nd125','Nd126','Nd127','Nd128','Nd129','Nd130','Nd131','Nd132','Nd133','Nd134','Nd135','Nd136','Nd137','Nd138','Nd139','Nd140','Nd141','Nd142','Nd143','Nd144','Nd145','Nd146','Nd147','Nd148','Nd149','Nd150','Nd151','Nd152','Nd153','Nd154','Nd155','Nd156','Nd157','Nd158','Nd159','Nd160','Nd161',...
            'Pm126','Pm127','Pm128','Pm129','Pm130','Pm131','Pm132','Pm133','Pm134','Pm135','Pm136','Pm137','Pm138','Pm139','Pm140','Pm141','Pm142','Pm143','Pm144','Pm145','Pm146','Pm147','Pm148','Pm149','Pm150','Pm151','Pm152','Pm153','Pm154','Pm155','Pm156','Pm157','Pm158','Pm159','Pm160','Pm161','Pm162','Pm163',...
            'Sm128','Sm129','Sm130','Sm131','Sm132','Sm133','Sm134','Sm135','Sm136','Sm137','Sm138','Sm139','Sm140','Sm141','Sm142','Sm143','Sm144','Sm145','Sm146','Sm147','Sm148','Sm149','Sm150','Sm151','Sm152','Sm153','Sm154','Sm155','Sm156','Sm157','Sm158','Sm159','Sm160','Sm161','Sm162','Sm163','Sm164','Sm165',...
            'Eu130','Eu131','Eu132','Eu133','Eu134','Eu135','Eu136','Eu137','Eu138','Eu139','Eu140','Eu141','Eu142','Eu143','Eu144','Eu145','Eu146','Eu147','Eu148','Eu149','Eu150','Eu151','Eu152','Eu153','Eu154','Eu155','Eu156','Eu157','Eu158','Eu159','Eu160','Eu161','Eu162','Eu163','Eu164','Eu165','Eu166','Eu167',...
            'Gd134','Gd135','Gd136','Gd137','Gd138','Gd139','Gd140','Gd141','Gd142','Gd143','Gd144','Gd145','Gd146','Gd147','Gd148','Gd149','Gd150','Gd151','Gd152','Gd153','Gd154','Gd155','Gd156','Gd157','Gd158','Gd159','Gd160','Gd161','Gd162','Gd163','Gd164','Gd165','Gd166','Gd167','Gd168','Gd169',...
            'Tb136','Tb137','Tb138','Tb139','Tb140','Tb141','Tb142','Tb143','Tb144','Tb145','Tb146','Tb147','Tb148','Tb149','Tb150','Tb151','Tb152','Tb153','Tb154','Tb155','Tb156','Tb157','Tb158','Tb159','Tb160','Tb161','Tb162','Tb163','Tb164','Tb165','Tb166','Tb167','Tb168','Tb169','Tb170','Tb171',...
            'Dy138','Dy139','Dy140','Dy141','Dy142','Dy143','Dy144','Dy145','Dy146','Dy147','Dy148','Dy149','Dy150','Dy151','Dy152','Dy153','Dy154','Dy155','Dy156','Dy157','Dy158','Dy159','Dy160','Dy161','Dy162','Dy163','Dy164','Dy165','Dy166','Dy167','Dy168','Dy169','Dy170','Dy171','Dy172','Dy173',...
            'Ho140','Ho141','Ho142','Ho143','Ho144','Ho145','Ho146','Ho147','Ho148','Ho149','Ho150','Ho151','Ho152','Ho153','Ho154','Ho155','Ho156','Ho157','Ho158','Ho159','Ho160','Ho161','Ho162','Ho163','Ho164','Ho165','Ho166','Ho167','Ho168','Ho169','Ho170','Ho171','Ho172','Ho173','Ho174','Ho175',...
            'Er143','Er144','Er145','Er146','Er147','Er148','Er149','Er150','Er151','Er152','Er153','Er154','Er155','Er156','Er157','Er158','Er159','Er160','Er161','Er162','Er163','Er164','Er165','Er166','Er167','Er168','Er169','Er170','Er171','Er172','Er173','Er174','Er175','Er176','Er177',...
            'Tm145','Tm146','Tm147','Tm148','Tm149','Tm150','Tm151','Tm152','Tm153','Tm154','Tm155','Tm156','Tm157','Tm158','Tm159','Tm160','Tm161','Tm162','Tm163','Tm164','Tm165','Tm166','Tm167','Tm168','Tm169','Tm170','Tm171','Tm172','Tm173','Tm174','Tm175','Tm176','Tm177','Tm178','Tm179',...
            'Yb148','Yb149','Yb150','Yb151','Yb152','Yb153','Yb154','Yb155','Yb156','Yb157','Yb158','Yb159','Yb160','Yb161','Yb162','Yb163','Yb164','Yb165','Yb166','Yb167','Yb168','Yb169','Yb170','Yb171','Yb172','Yb173','Yb174','Yb175','Yb176','Yb177','Yb178','Yb179','Yb180','Yb181','Yb182',...
            'Lu150','Lu151','Lu152','Lu153','Lu154','Lu155','Lu156','Lu157','Lu158','Lu159','Lu160','Lu161','Lu162','Lu163','Lu164','Lu165','Lu166','Lu167','Lu168','Lu169','Lu170','Lu171','Lu172','Lu173','Lu174','Lu175','Lu176','Lu177','Lu178','Lu179','Lu180','Lu181','Lu182','Lu183','Lu184',...
            'Hf153','Hf154','Hf155','Hf156','Hf157','Hf158','Hf159','Hf160','Hf161','Hf162','Hf163','Hf164','Hf165','Hf166','Hf167','Hf168','Hf169','Hf170','Hf171','Hf172','Hf173','Hf174','Hf175','Hf176','Hf177','Hf178','Hf179','Hf180','Hf181','Hf182','Hf183','Hf184','Hf185','Hf186','Hf187','Hf188',...
            'Ta155','Ta156','Ta157','Ta158','Ta159','Ta160','Ta161','Ta162','Ta163','Ta164','Ta165','Ta166','Ta167','Ta168','Ta169','Ta170','Ta171','Ta172','Ta173','Ta174','Ta175','Ta176','Ta177','Ta178','Ta179','Ta180','Ta181','Ta182','Ta183','Ta184','Ta185','Ta186','Ta187','Ta188','Ta189','Ta190',...
            'W158','W159','W160','W161','W162','W163','W164','W165','W166','W167','W168','W169','W170','W171','W172','W173','W174','W175','W176','W177','W178','W179','W180','W181','W182','W183','W184','W185','W186','W187','W188','W189','W190','W191','W192',...
            'Re160','Re161','Re162','Re163','Re164','Re165','Re166','Re167','Re168','Re169','Re170','Re171','Re172','Re173','Re174','Re175','Re176','Re177','Re178','Re179','Re180','Re181','Re182','Re183','Re184','Re185','Re186','Re187','Re188','Re189','Re190','Re191','Re192','Re193','Re194',...
            'Os162','Os163','Os164','Os165','Os166','Os167','Os168','Os169','Os170','Os171','Os172','Os173','Os174','Os175','Os176','Os177','Os178','Os179','Os180','Os181','Os182','Os183','Os184','Os185','Os186','Os187','Os188','Os189','Os190','Os191','Os192','Os193','Os194','Os195','Os196',...
            'Ir164','Ir165','Ir166','Ir167','Ir168','Ir169','Ir170','Ir171','Ir172','Ir173','Ir174','Ir175','Ir176','Ir177','Ir178','Ir179','Ir180','Ir181','Ir182','Ir183','Ir184','Ir185','Ir186','Ir187','Ir188','Ir189','Ir190','Ir191','Ir192','Ir193','Ir194','Ir195','Ir196','Ir197','Ir198','Ir199',...
            'Pt166','Pt167','Pt168','Pt169','Pt170','Pt171','Pt172','Pt173','Pt174','Pt175','Pt176','Pt177','Pt178','Pt179','Pt180','Pt181','Pt182','Pt183','Pt184','Pt185','Pt186','Pt187','Pt188','Pt189','Pt190','Pt191','Pt192','Pt193','Pt194','Pt195','Pt196','Pt197','Pt198','Pt199','Pt200','Pt201','Pt202',...
            'Au169','Au170','Au171','Au172','Au173','Au174','Au175','Au176','Au177','Au178','Au179','Au180','Au181','Au182','Au183','Au184','Au185','Au186','Au187','Au188','Au189','Au190','Au191','Au192','Au193','Au194','Au195','Au196','Au197','Au198','Au199','Au200','Au201','Au202','Au203','Au204','Au205',...
            'Hg171','Hg172','Hg173','Hg174','Hg175','Hg176','Hg177','Hg178','Hg179','Hg180','Hg181','Hg182','Hg183','Hg184','Hg185','Hg186','Hg187','Hg188','Hg189','Hg190','Hg191','Hg192','Hg193','Hg194','Hg195','Hg196','Hg197','Hg198','Hg199','Hg200','Hg201','Hg202','Hg203','Hg204','Hg205','Hg206','Hg207','Hg208','Hg209','Hg210',...
            'Tl176','Tl177','Tl178','Tl179','Tl180','Tl181','Tl182','Tl183','Tl184','Tl185','Tl186','Tl187','Tl188','Tl189','Tl190','Tl191','Tl192','Tl193','Tl194','Tl195','Tl196','Tl197','Tl198','Tl199','Tl200','Tl201','Tl202','Tl203','Tl204','Tl205','Tl206','Tl207','Tl208','Tl209','Tl210','Tl211','Tl212',...
            'Pb178','Pb179','Pb180','Pb181','Pb182','Pb183','Pb184','Pb185','Pb186','Pb187','Pb188','Pb189','Pb190','Pb191','Pb192','Pb193','Pb194','Pb195','Pb196','Pb197','Pb198','Pb199','Pb200','Pb201','Pb202','Pb203','Pb204','Pb205','Pb206','Pb207','Pb208','Pb209','Pb210','Pb211','Pb212','Pb213','Pb214','Pb215',...
            'Bi184','Bi185','Bi186','Bi187','Bi188','Bi189','Bi190','Bi191','Bi192','Bi193','Bi194','Bi195','Bi196','Bi197','Bi198','Bi199','Bi200','Bi201','Bi202','Bi203','Bi204','Bi205','Bi206','Bi207','Bi208','Bi209','Bi210','Bi211','Bi212','Bi213','Bi214','Bi215','Bi216','Bi217','Bi218','Bi219',...
            'Po188','Po189','Po190','Po191','Po192','Po193','Po194','Po195','Po196','Po197','Po198','Po199','Po200','Po201','Po202','Po203','Po204','Po205','Po206','Po207','Po208','Po209','Po210','Po211','Po212','Po213','Po214','Po215','Po216','Po217','Po218','Po219','Po220',...
            'At193','At194','At195','At196','At197','At198','At199','At200','At201','At202','At203','At204','At205','At206','At207','At208','At209','At210','At211','At212','At213','At214','At215','At216','At217','At218','At219','At220','At221','At222','At223',...
            'Rn195','Rn196','Rn197','Rn198','Rn199','Rn200','Rn201','Rn202','Rn203','Rn204','Rn205','Rn206','Rn207','Rn208','Rn209','Rn210','Rn211','Rn212','Rn213','Rn214','Rn215','Rn216','Rn217','Rn218','Rn219','Rn220','Rn221','Rn222','Rn223','Rn224','Rn225','Rn226','Rn227','Rn228',...
            'Fr199','Fr200','Fr201','Fr202','Fr203','Fr204','Fr205','Fr206','Fr207','Fr208','Fr209','Fr210','Fr211','Fr212','Fr213','Fr214','Fr215','Fr216','Fr217','Fr218','Fr219','Fr220','Fr221','Fr222','Fr223','Fr224','Fr225','Fr226','Fr227','Fr228','Fr229','Fr230','Fr231','Fr232',...
            'Ra202','Ra203','Ra204','Ra205','Ra206','Ra207','Ra208','Ra209','Ra210','Ra211','Ra212','Ra213','Ra214','Ra215','Ra216','Ra217','Ra218','Ra219','Ra220','Ra221','Ra222','Ra223','Ra224','Ra225','Ra226','Ra227','Ra228','Ra229','Ra230','Ra231','Ra232','Ra233','Ra234',...
            'Ac206','Ac207','Ac208','Ac209','Ac210','Ac211','Ac212','Ac213','Ac214','Ac215','Ac216','Ac217','Ac218','Ac219','Ac220','Ac221','Ac222','Ac223','Ac224','Ac225','Ac226','Ac227','Ac228','Ac229','Ac230','Ac231','Ac232','Ac233','Ac234','Ac235','Ac236',...
            'Th209','Th210','Th211','Th212','Th213','Th214','Th215','Th216','Th217','Th218','Th219','Th220','Th221','Th222','Th223','Th224','Th225','Th226','Th227','Th228','Th229','Th230','Th231','Th232','Th233','Th234','Th235','Th236','Th237','Th238',...
            'Pa212','Pa213','Pa214','Pa215','Pa216','Pa217','Pa218','Pa219','Pa220','Pa221','Pa222','Pa223','Pa224','Pa225','Pa226','Pa227','Pa228','Pa229','Pa230','Pa231','Pa232','Pa233','Pa234','Pa235','Pa236','Pa237','Pa238','Pa239','Pa240',...
            'U217','U218','U219','U220','U221','U222','U223','U224','U225','U226','U227','U228','U229','U230','U231','U232','U233','U234','U235','U236','U237','U238','U239','U240','U241','U242',...
            'Np225','Np226','Np227','Np228','Np229','Np230','Np231','Np232','Np233','Np234','Np235','Np236','Np237','Np238','Np239','Np240','Np241','Np242','Np243','Np244',...
            'Pu228','Pu229','Pu230','Pu231','Pu232','Pu233','Pu234','Pu235','Pu236','Pu237','Pu238','Pu239','Pu240','Pu241','Pu242','Pu243','Pu244','Pu245','Pu246','Pu247',...
            'Am231','Am232','Am233','Am234','Am235','Am236','Am237','Am238','Am239','Am240','Am241','Am242','Am243','Am244','Am245','Am246','Am247','Am248','Am249',...
            'Cm233','Cm234','Cm235','Cm236','Cm237','Cm238','Cm239','Cm240','Cm241','Cm242','Cm243','Cm244','Cm245','Cm246','Cm247','Cm248','Cm249','Cm250','Cm251','Cm252',...
            'Bk235','Bk236','Bk237','Bk238','Bk239','Bk240','Bk241','Bk242','Bk243','Bk244','Bk245','Bk246','Bk247','Bk248','Bk249','Bk250','Bk251','Bk252','Bk253','Bk254',...
            'Cf237','Cf238','Cf239','Cf240','Cf241','Cf242','Cf243','Cf244','Cf245','Cf246','Cf247','Cf248','Cf249','Cf250','Cf251','Cf252','Cf253','Cf254','Cf255','Cf256',...
            'Es240','Es241','Es242','Es243','Es244','Es245','Es246','Es247','Es248','Es249','Es250','Es251','Es252','Es253','Es254','Es255','Es256','Es257','Es258',...
            'Fm242','Fm243','Fm244','Fm245','Fm246','Fm247','Fm248','Fm249','Fm250','Fm251','Fm252','Fm253','Fm254','Fm255','Fm256','Fm257','Fm258','Fm259','Fm260',...
            'Md245','Md246','Md247','Md248','Md249','Md250','Md251','Md252','Md253','Md254','Md255','Md256','Md257','Md258','Md259','Md260','Md261','Md262',...
            'No248','No249','No250','No251','No252','No253','No254','No255','No256','No257','No258','No259','No260','No261','No262','No263','No264',...
            'Lr251','Lr252','Lr253','Lr254','Lr255','Lr256','Lr257','Lr258','Lr259','Lr260','Lr261','Lr262','Lr263','Lr264','Lr265','Lr266',...
            'Rf253','Rf254','Rf255','Rf256','Rf257','Rf258','Rf259','Rf260','Rf261','Rf262','Rf263','Rf264','Rf265','Rf266','Rf267','Rf268',...
            'Db255','Db256','Db257','Db258','Db259','Db260','Db261','Db262','Db263','Db264','Db265','Db266','Db267','Db268','Db269','Db270',...
            'Sg258','Sg259','Sg260','Sg261','Sg262','Sg263','Sg264','Sg265','Sg266','Sg267','Sg268','Sg269','Sg270','Sg271','Sg272','Sg273',...
            'Bh260','Bh261','Bh262','Bh263','Bh264','Bh265','Bh266','Bh267','Bh268','Bh269','Bh270','Bh271','Bh272','Bh273','Bh274','Bh275',...
            'Hs263','Hs264','Hs265','Hs266','Hs267','Hs268','Hs269','Hs270','Hs271','Hs272','Hs273','Hs274','Hs275','Hs276','Hs277',...
            'Mt265','Mt266','Mt267','Mt268','Mt269','Mt270','Mt271','Mt272','Mt273','Mt274','Mt275','Mt276','Mt277','Mt278','Mt279',...
            'Ds267','Ds268','Ds269','Ds270','Ds271','Ds272','Ds273','Ds274','Ds275','Ds276','Ds277','Ds278','Ds279','Ds280','Ds281',...
            'Rg272','Rg273','Rg274','Rg275','Rg276','Rg277','Rg278','Rg279','Rg280','Rg281','Rg282','Rg283',...
            'Cn277','Cn278','Cn279','Cn280','Cn281','Cn282','Cn283','Cn284','Cn285','Nh283','Nh284','Nh285','Nh286','Nh287',...
            'Fl285','Fl286','Fl287','Fl288','Fl289','Mc287','Mc288','Mc289','Mc290','Mc291','Lv289','Lv290','Lv291','Lv292','Ts291','Ts292','Ts293','Ts294','Og293'};


        ElementList={'H','He','Li','Be','B','C','N','O','F','Ne','Na','Mg','Al','Si','P','S','Cl',...
            'Ar','K','Ca','Sc','Ti','V','Cr','Mn','Fe','Co','Ni','Cu','Zn','Ga','Ge','As',...
            'Se','Br','Kr','Rb','Sr','Y','Zr','Nb','Mo','Tc','Ru','Rh','Pd','Ag','Cd','In',...
            'Sn','Sb','Te','I','Xe','Cs','Ba','La','Ce','Pr','Nd','Pm','Sm','Eu','Gd','Tb',...
            'Dy','Ho','Er','Tm','Yb','Lu','Hf','Ta','W','Re','Os','Ir','Pt','Au','Hg','Tl',...
            'Pb','Bi','Po','At','Rn','Fr','Ra','Ac','Th','Pa','U','Np','Pu','Am','Cm','Bk',...
            'Cf','Es','Fm','Md','No','Lr','Rf','Db','Sg','Bh','Hs','Mt','Uun','Uuu','Uub'};
        %future update add context menu edit multiple
        TableRaw

        DetectionLimitsTable % Description
        DetectionLimitsTableRaw % Description

        d % Description
        DiagramsDefinitionTable % Description
        DiagramsTable % Description
    end

    properties (Access = public)
        MinPlotX % Description
    end

    methods (Access = private)

        function DisplayError(app, ME,WhichOperation)
            %DisplayError - Displays detailed error information
            % Description:
            %    This function displays detailed information about an error, including the
            %    time of occurrence, the operation during which the error occurred, and the
            %    stack trace of the error. If the WhichOperation parameter is not provided,
            %    it defaults to '?'. In deployed software the error message is written into the log_file.txt
            % Author: Nils B. Gies
            % Last edit: 2025-01-05
            % Inputs:
            %    ME - The MException object containing error information
            %    WhichOperation - A string describing the operation during which the error occurred
            %
            try
                if not(exist("WhichOperation","var"))
                    disp('Error! Missing ME!')
                    return
                end

                if not(exist("WhichOperation","var"))
                    WhichOperation='?';
                end

                disp(' ')
                disp('_-_-_-_-_-_-_-_-_-_-_-_-_-_-_-_-_-_-_-_-_')
                disp('%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%')
                disp('%%%%%%%%%%%%%%   Error   %%%%%%%%%%%%%%%%')
                disp('%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%')
                disp('%%')
                disp(['%%        ' char(datetime('now'))])
                disp('%%')
                disp('%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%')
                disp(['Error occured during: ' WhichOperation])
                % for n=1
                disp('_________________________________________')
                k=1;
                for n=numel(ME.stack):-1:1
                    disp(['%%' num2str(k,'%03.f') '%%'])
                    k=1+k;
                    disp('Error in file:')
                    disp(ME.stack(n).file)
                    disp(['Error in line: ',num2str(ME.stack(n).line)])
                    disp('_________________________________________')
                    disp('  ')
                end
                disp(['Error in message: ' ME.message])
                disp('  ')
                disp('%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%')
                disp('_-_-_-_-_-_-_-_-_-_-_-_-_-_-_-_-_-_-_-_-_')
                disp('  ')
                disp('  ')
            catch ME
                disp('Error occured during: DisplayError(app, ME,WhichOperation)')
                for n=numel(ME.stack):-1:1
                    disp('Error in file:')
                    disp(ME.stack(n).file)
                    disp(['Error in line: ',num2str(ME.stack(n).line)])
                    disp('_________________________________________')
                    disp('  ')
                end
            end
        end


        function update_GUI(app)
            if not(isfield(app.MinPlotX,'Standalone'))
                UpdateFileTree(app)
                update_table(app);
            else

            end
            UpdateVariableTree(app)
            VariableDropDownOpening(app)
            update_class_table(app)
        end

        function ResetTree(app,Tree2Update)

            if not(exist('Tree2Update','var'))
                Tree2Update=app.Tree_2;
            end
            a = Tree2Update.Children;
            a.delete;
        end

        function UpdateVariableTree(app)

            ResetTree(app,app.VarSelectionTree)
            if isempty(app.UITable.Data)
                ColNames={};
            else
                test=any(cellfun(@isnumeric,table2cell(app.UITable.Data)));
                ColNames=app.UITable.ColumnName;
                for n=1:numel(ColNames)
                    if test(n)==true

                        uitreenode(app.VarSelectionTree,"Text",ColNames{n},'NodeData',app.UITable.Data(:,n));

                    end
                end

                ColNames=app.UITable.ColumnName(not(any(cellfun(@isnumeric,table2cell(app.UITable.Data)))|any(cellfun(@islogical,table2cell(app.UITable.Data)))));
            end
            app.ListBox.Items=ColNames;


        end

        function UpdateFileTree(app)
            if isfield(app.MinPlotX,'Standalone')
                return
            end
            Tree2Update=app.Tree_2;
            N=Tree2Update.CheckedNodes;

            if not(isempty(N))


                selectedNodes=cell2mat({N.NodeData}');

                T_C=cell2mat({Tree2Update.Children.NodeData}');
                T_C=Tree2Update.Children(T_C(ismember(T_C,unique(selectedNodes(:,1)))));
                N=[N;T_C];

                selectedNodes_T={N.Text}';
                selectedNodes=cell2mat({N.NodeData}');

                update_ts=true;
            else
                update_ts=false;
                selectedNodes=[];
            end

            %             ResetTree(app,app.Tree_2)
            %
            %             app.CheckallMenu = uimenu(app.ContextMenu);
            %             app.CheckallMenu.MenuSelectedFcn = createCallbackFcn(app, @CheckallMenuSelected, true);
            %             app.CheckallMenu.Text = 'Check all';
            %
            %             % Create UncheckallMenu
            %             app.UncheckallMenu = uimenu(app.ContextMenu);
            %             app.UncheckallMenu.MenuSelectedFcn = createCallbackFcn(app, @UncheckallMenuSelected, true);
            %             app.UncheckallMenu.Text = 'Uncheck all';
            %
            %             % Assign app.ContextMenu


            if strcmp(app.DropDown.Value,'Sort By File')

                for n=1:numel(app.MinPlotX.MinPlotXData.SampleNames)
                    p0 = uitreenode(Tree2Update,'Text',string(app.MinPlotX.MinPlotXData.SampleNames{n}),'NodeData',[n,0]);
                    %   p.ContextMenu = ContextMenu_thx;

                    p0.ContextMenu =  app.ContextMenu;

                    Mineral=unique(app.MinPlotX.MinPlotXData.Sample(n).Input.MetaTable.Mineral,'stable');
                    if numel(Mineral) > 0
                        for i = 1:numel(Mineral)

                            [T,T_EPMAData,T_MSData,T_BULKData,T_MetaData,T_StrctFrm,T_apfu,T_CalcOut,T_options,T_FileInfo,T_err_per,T_err_abs]=get_compiled_table(app.MinPlotX,n); %#ok

                            which_row=strcmp(T_MetaData.Mineral,Mineral{i});

                            if not(isempty(T_FileInfo))
                                Data.FileInfo=T_FileInfo(which_row,:);
                            else
                                Data.FileInfo=T_FileInfo;
                            end


                            if not(isempty(T_EPMAData))
                                Data.EPMAData=T_EPMAData(which_row,:);
                            else
                                Data.EPMAData=T_EPMAData;
                            end

                            if not(isempty(T_MetaData))
                                Data.MetaData=T_MetaData(which_row,:);
                            else
                                Data.MetaData=T_MetaData;
                            end
                            if not(isempty(T_StrctFrm))
                                Data.StrctFrm=T_StrctFrm(which_row,:);
                            else
                                Data.StrctFrm=T_StrctFrm;
                            end

                            if not(isempty(T_apfu))
                                Data.apfu=T_apfu(which_row,:);
                            else
                                Data.apfu=T_apfu;
                            end

                            if not(isempty(T_CalcOut))
                                Data.CalcOut=T_CalcOut(which_row,:);
                            else
                                Data.CalcOut=T_CalcOut;
                            end

                            if not(isempty(T_options))
                                Data.options=T_options(which_row,:);
                            else
                                Data.options=T_options;
                            end

                            if not(isempty(T_err_abs))
                                Data.err_abs=T_err_abs(which_row,:);
                            else
                                Data.err_abs=T_err_abs;
                            end

                            if not(isempty(T_err_per))
                                Data.err_per=T_err_per(which_row,:);
                            else
                                Data.err_per=T_err_per;
                            end

                            p = uitreenode(p0,'Text',string(Mineral{i}),'NodeData',Data);


                            p.ContextMenu =  app.ContextMenu;

                            %                         if not(strcmp('unknown',char(CheckMinName(app.MinPlotX,Mineral{i}))))
                            %                             u_MNs= app.MinPlotX.MineralCalcFunctions.(char(CheckMinName(app.MinPlotX,Mineral{i})));
                            %                             for j = 1:length(u_MNs)
                            %                                 if any(not(ismissing(u_MNs{j})))
                            %                                     p1 = uitreenode(p,'Text',string(u_MNs{j}),'NodeData',[i,j]);
                            %
                            %                                     %   p1.ContextMenu = ContextMenu_thx;
                            %                                 end
                            %                             end
                            %                         end
                        end
                    end
                end
            else
                Mineral={};
                for n=1:numel(app.MinPlotX.MinPlotXData.SampleNames)
                    Mineral=unique([Mineral; app.MinPlotX.MinPlotXData.Sample(n).Input.MetaTable.Mineral],'stable');
                end

                if numel(Mineral) > 0
                    for i = 1:numel(Mineral)
                        p = uitreenode(Tree2Update,'Text',string(Mineral{i}),'NodeData',[i,0]);
                        p.ContextMenu =  app.ContextMenu;
                        for n=1:numel(app.MinPlotX.MinPlotXData.SampleNames)
                            if any(ismember(app.MinPlotX.MinPlotXData.Sample(n).Input.MetaTable.Mineral,Mineral{i}))

                                % [T,T_EPMAData,T_MSData,T_BULKData,T_MetaData,T_StrctFrm,T_apfu,T_CalcOut,T_options,T_FileInfo]=get_compiled_table(app.MinPlotX,n); %#ok
                                [T,T_EPMAData,T_MSData,T_BULKData,T_MetaData,T_StrctFrm,T_apfu,T_CalcOut,T_options,T_FileInfo,T_err_per,T_err_abs]=get_compiled_table(app.MinPlotX,n); %#ok

                                which_row=strcmp(T_MetaData.Mineral,Mineral{i});

                                if not(isempty(T_FileInfo))
                                    Data.FileInfo=T_FileInfo(which_row,:);
                                else
                                    Data.FileInfo=T_FileInfo;
                                end

                                if not(isempty(T_EPMAData))
                                    Data.EPMAData=T_EPMAData(which_row,:);
                                else
                                    Data.EPMAData=T_EPMAData;
                                end

                                                    if not(isempty(T_MSData))
                                    Data.MSData=T_MSData(which_row,:);
                                else
                                    Data.MSData=T_MSData;
                                                    end

                                                                           if not(isempty(T_BULKData))
                                    Data.MSData=T_BULKData(which_row,:);
                                else
                                    Data.MSData=T_BULKData;
                                                                           end


                                if not(isempty(T_MetaData))
                                    Data.MetaData=T_MetaData(which_row,:);
                                else
                                    Data.MetaData=T_MetaData;
                                end
                                if not(isempty(T_StrctFrm))
                                    Data.StrctFrm=T_StrctFrm(which_row,:);
                                else
                                    Data.StrctFrm=T_StrctFrm;
                                end

                                if not(isempty(T_apfu))
                                    Data.apfu=T_apfu(which_row,:);
                                else
                                    Data.apfu=T_apfu;
                                end

                                if not(isempty(T_options))
                                    Data.options=T_options(which_row,:);
                                else
                                    Data.options=T_options;
                                end

                                if not(isempty(T_err_abs))
                                    Data.err_abs=T_err_abs(which_row,:);
                                else
                                    Data.err_abs=T_err_abs;
                                end

                                if not(isempty(T_err_per))
                                    Data.err_per=T_err_per(which_row,:);
                                else
                                    Data.err_per=T_err_per;
                                end

                                p0 = uitreenode(p,'Text',string(app.MinPlotX.MinPlotXData.SampleNames{n}),'NodeData',Data);
                                p0.ContextMenu =  app.ContextMenu;

                            end
                        end
                    end
                end


            end


            if update_ts==true

                try
                    TreeNodes_new={Tree2Update.Children.Text};
                    nodes2check_P=Tree2Update.Children(contains(TreeNodes_new,selectedNodes_T(selectedNodes(:,2)==0)));
                    P_old=unique(selectedNodes(selectedNodes(:,2)==0,1),'row');
                    nodes2check_final= [];
                    for n=1:numel(nodes2check_P)
                        nodes2check_P_C=(selectedNodes_T(selectedNodes(:,2)>0&selectedNodes(:,1)==P_old(n)));
                        nodes2check_final=[nodes2check_final; nodes2check_P(n).Children(contains({nodes2check_P(n).Children.Text},nodes2check_P_C))  ];%#ok
                    end
                    Tree2Update.CheckedNodes=nodes2check_final;
                    expand(Tree2Update)
                catch ME
                    DisplayError(app, ME,'UpdateTree')


                end

            end

            if numel(app.MinPlotX.MinPlotXData.SampleNames)==1
                CheckallMenuSelected(app)
            end

        end


        function T_out=update_table(app)
            app.d = uiprogressdlg(app.VisModule_GUI,'Title','Updating Table',...
                'Message','Updating Table','Cancelable','off');
            try
                if isfield(app.MinPlotX,'Standalone')
                    T_out=app.MinPlotX;

                    %ATH sort_T_out_here
                    if strcmp('Active',T_out.Properties.VariableNames)
                        thx=matlab.lang.makeUniqueStrings([{'Active'} T_out.Properties.VariableNames]);
                        T_out.Properties.VariableNames=thx(2:end);
                    end

                    app.UITable.Data=[table(true(size(T_out,1),1),'VariableNames',{'Active'}) T_out];
                    app.UITable.ColumnEditable=[true false(1,size(T_out,2))];
                    app.UITable.ColumnName=[{'Active'} T_out.Properties.VariableNames];
                    UpdateVariableTree(app)

                else

                    checkedNodes= app.Tree_2.CheckedNodes;
                    if isempty(checkedNodes) || isempty(app.Tree.CheckedNodes)
                        app.UITable.Data=table();
                        delete(app.d)
                          VariableDropDownOpening(app)
                            update_class_table(app)
                        return
                    end

                    ND_cell={checkedNodes.NodeData};
                    %             ND_names={checkedNodes.Text};
                    %             ND_names=ND_names(cellfun(@isstruct,ND_cell));

                    ND_cell=ND_cell(cellfun(@isstruct,ND_cell));
                    ND_struct=[ND_cell{:}];

                    for n=1:numel(ND_cell)

                        T=table();


                        if any(ismember({app.Tree.CheckedNodes.Text}, 'FileInfo'))
                            T=combine_table_fun(app,T,ND_struct(n).FileInfo);
                            %T=[T ND_struct(n).FileInfo];%#ok
                        end

                        if any(ismember({app.Tree.CheckedNodes.Text}, 'EPMA'))
                            T=combine_table_fun(app,T,ND_struct(n).EPMAData);
                            % T=[T ND_struct(n).EPMAData];%#ok
                        end

                        if any(ismember({app.Tree.CheckedNodes.Text}, 'Metadata'))
                            T=combine_table_fun(app,T,ND_struct(n).MetaData);
                            %    T=[T ND_struct(n).MetaData];%#ok
                        else
                            if not(isempty(ND_struct(n).MetaData))
                                if any(ismember(ND_struct(n).MetaData.Properties.VariableNames,'ProfileDistance'))
                                    T=combine_table_fun(app,T,ND_struct(n).MetaData(:,ismember(ND_struct(n).MetaData.Properties.VariableNames,'ProfileDistance')));
                                    %  T=[T ND_struct(n).MetaData(:,ismember(ND_struct(n).MetaData.Properties.VariableNames,'ProfileDistance'))];%#ok
                                end
                                if  any(ismember(ND_struct(n).MetaData.Properties.VariableNames,'x_pos'))
                                    T=combine_table_fun(app,T,ND_struct(n).MetaData(:,ismember(ND_struct(n).MetaData.Properties.VariableNames,'x_pos')));
                                    %    T=[T ND_struct(n).MetaData(:,ismember(ND_struct(n).MetaData.Properties.VariableNames,'x_pos'))];%#ok
                                end
                                if any(ismember(ND_struct(n).MetaData.Properties.VariableNames,'y_pos'))
                                    T=combine_table_fun(app,T,ND_struct(n).MetaData(:,ismember(ND_struct(n).MetaData.Properties.VariableNames,'y_pos')));
                                    %    T=[T ND_struct(n).MetaData(:,ismember(ND_struct(n).MetaData.Properties.VariableNames,'y_pos'))];%#ok
                                end
                                if any(ismember(ND_struct(n).MetaData.Properties.VariableNames,'x_pos'))&& any(ismember(ND_struct(n).MetaData.Properties.VariableNames,'y_pos'))
                                    %ATH here turn on the options for spatial plotting
                                end
                            end
                        end

                        if any(ismember({app.Tree.CheckedNodes.Text}, 'apfu'))
                            T=combine_table_fun(app,T,ND_struct(n).apfu);
                            % T=[T ND_struct(n).apfu];%#ok
                        end

                        if any(ismember({app.Tree.CheckedNodes.Text}, 'Structural Formular'))
                            T=combine_table_fun(app,T,ND_struct(n).StrctFrm);
                            % T=[T ND_struct(n).StrctFrm];%#ok
                        end

                        if any(ismember({app.Tree.CheckedNodes.Text}, 'CalcOut'))
                            T=combine_table_fun(app,T,ND_struct(n).CalcOut);
                            %  T=[T ND_struct(n).CalcOut];%#ok
                        end


                        if any(ismember({app.Tree.CheckedNodes.Text}, 'Options'))

                            T=combine_table_fun(app,T,ND_struct(n).options);
                            %  T=[T ND_struct(n).options];%#ok
                        end


                        T_cell{n}=T;%#ok

                    end

                    T_out=tablevertcat_NG(app.MinPlotX,T_cell);

                    %ATH sort_T_out_here
                    if strcmp('Active',T_out.Properties.VariableNames)
                        thx=matlab.lang.makeUniqueStrings([{'Active'} T_out.Properties.VariableNames]);
                        T_out.Properties.VariableNames=thx(2:end);
                    end

                    app.UITable.Data=[table(true(size(T_out,1),1),'VariableNames',{'Active'}) T_out];
                    app.UITable.ColumnEditable=[true false(1,size(T_out,2))];
                    app.UITable.ColumnName=[{'Active'} T_out.Properties.VariableNames];
                end
                UpdateVariableTree(app)
                UserNormCheckBoxValueChanged(app)
                UseDataSubdivisionCheckBoxValueChanged(app)
                AnnotationCheckBoxValueChanged(app)
                VariableDropDownOpening(app)
                            update_class_table(app)

            catch ME
                DisplayError(app, ME,'VisModule - Update Table')
            end
            delete(app.d)
        end

        function symb=IterateMarkerSymbol(app,symb)
            if not(exist('symb','var'))
                symb=app.SymbolDropDown.Value;
            end

            if strcmp(symb,'o')
                symb='d';
            elseif strcmp(symb,'d')
                symb='s';
            elseif strcmp(symb,'s')
                symb='v';
            elseif strcmp(symb,'v')
                symb='>';
            elseif strcmp(symb,'>')
                symb='<';
            elseif strcmp(symb,'<')
                symb='^';
            elseif strcmp(symb,'none')
                symb='none';
            else
                symb='o';
            end
        end

        function color=IterateMarkerColor(app,color)
            if not(exist('color','var'))
                color=app.MarkerFaceColorDropDown.Value;
            end

            if any(ismember(app.MarkerFaceColorDropDown.ItemsData,color))&& find(ismember(app.MarkerFaceColorDropDown.ItemsData,color))<8

                color=app.MarkerFaceColorDropDown.ItemsData{find(ismember(app.MarkerFaceColorDropDown.ItemsData,color))+1};
            else
                color=app.MarkerFaceColorDropDown.ItemsData{1};
            end
        end


        function send_to_EasyPlot(app,settings)
            if ~isfield(settings,'noprogressbar')
                app.d = uiprogressdlg(app.VisModule_GUI,'Title','Generating Plot',...
                    'Message','Plotting...','Cancelable','off');
            else
                app.d=0;
            end
            try
                if isfield(settings,'which')
                    which=settings.which;
                else
                    settings.NewPlot=true;
                    which='Scatter_Plot';
                end
                LineAlpha=1;

                checkedNodes = app.VarSelectionTree.CheckedNodes;
                if startsWith(which,'custom_') || not (isempty(checkedNodes))
                    existing_objects={};
                    if isfield(settings,'ax2plot')
                        ax2plot=settings.ax2plot;
                    else
                        ax2plot=[];
                    end
                    legend_str=[];


                    if app.AnnotationCheckBox.Value==true
                        settings.custom.Annotation=true;
                        settings.custom.AnnotationColumn=app.AnnotationListBox.Value;
                    end

                    if    not(isfield(app.MinPlotX.MinPlotXFigures,'hFig_type')) || isempty(app.MinPlotX.MinPlotXFigures) || isempty(app.MinPlotX.MinPlotXFigures.hFig_type) % or handle not valid
                        hFig_id=1;
                    elseif isfield(app.MinPlotX.MinPlotXFigures,'hFig_type') && exist('which','var') && ismember(which,app.MinPlotX.MinPlotXFigures.hFig_type) && not(isfield(settings,'NewPlot')&& settings.NewPlot==true)
                        hFig_id=find(strcmp(app.MinPlotX.MinPlotXFigures.hFig_type,which));
                        ax2plot=app.MinPlotX.MinPlotXFigures.hFig(hFig_id).ax2plot;
                        scatter_obj=flipud(findobj('Parent',ax2plot,'Type','Scatter'));
                        if not(isempty(scatter_obj))
                            existing_objects={scatter_obj.DisplayName};
                        end
                        which=app.MinPlotX.MinPlotXFigures.hFig_type{hFig_id};
                    else
                        if isfield(app.MinPlotX.MinPlotXFigures,'hFig_type') && not(isempty(app.MinPlotX.MinPlotXFigures.hFig_type)) && ismember(which,app.MinPlotX.MinPlotXFigures.hFig_type)
                            names_unique = matlab.lang.makeUniqueStrings([app.MinPlotX.MinPlotXFigures.hFig_type which]);
                            which=names_unique{end};
                            %ath end with XYZ
                        end

                        hFig_id=numel(app.MinPlotX.MinPlotXFigures.hFig_type)+1;
                    end


                    if isempty(ax2plot)||not(isgraphics(ax2plot))
                        if startsWith(which,'custom_XYZ') || startsWith(which,'custom_ratio_XYZ')
                            settings.XYZplot.Value=true;
                            [ax2plot,~]=plot_XYZ_fun([],settings);
                            hfig=ax2plot.Parent(1).Parent(1);
                        elseif startsWith(which,'custom_XY') || startsWith(which,'custom_ratio_XY')
                            settings.XYplot.Value=true;
                            [ax2plot,~]=plot_XY_fun([],settings);
                            hfig=ax2plot.Parent(1).Parent(1);
                        elseif startsWith(which,'custom_tern')
                            settings.plot_tern.Value=true;
                            [ax2plot,~]=plot_tern_fun([],settings);
                            hfig=ax2plot.Parent(1).Parent(1);
                        elseif startsWith(which,'custom_tetra')
                            settings.tetraplot.Value=true;
                            [ax2plot,~]=plot_tetra_fun([],settings);
                            hfig=ax2plot.Parent(1).Parent(1);
                        elseif startsWith(which,'custom_TraceElements')
                            [ax2plot,~]=plot_TE_fun([],settings);
                            hfig=ax2plot.Parent(1).Parent(1);
                        else
                            hfig=figure;
                            ax2plot=nexttile;
                        end
                        try
                            CenterFig_fun(hfig)
                        catch
                        end
                        try


%                             if isfield(app.MinPlotX,'Standalone')
%                                 hfig.CloseRequestFcn = @(src, event) EasyPlotCloseRequestFcn([],src, event);
%                             else
%                                 hfig.CloseRequestFcn = @(src, event) EasyPlotCloseRequestFcn(app.MinPlotX,src, event);
%                             end

% Store reference safely inside figure
if isprop(app,'MinPlotX') && ~isempty(app.MinPlotX) && isa(app.MinPlotX,'handle') %(~isfield(app.MinPlotX,'Standalone') || app.MinPlotX.Standalone==false) && 
    setappdata(hfig,'mainapp',app.MinPlotX);
else
    setappdata(hfig,'mainapp',[]);
end
hfig.CloseRequestFcn = @(src,event) EasyPlotCloseRequestFcn(src,event);

                        catch ME
                            DisplayError(app, ME)
                            delete(app.d)
                            delete(hfig);
                            return
                        end

                        if startsWith(which,'custom_XYZ')
                            hfig.Name='Custom XYZ Plot';
                        elseif startsWith(which,'custom_XY')
                            hfig.Name='Custom XY Plot';
                        elseif startsWith(which,'custom_tern')
                            hfig.Name='Custom Ternary Plot';
                        elseif startsWith(which,'custom_tetra')
                            hfig.Name='Custom Tetrahedron Plot';
                        elseif startsWith(which,'custom_ratio_XYZ')
                            hfig.Name='Custom Ratio XYZ Plot';
                        elseif startsWith(which,'custom_ratio_XY')
                            hfig.Name='Custom Ratio XY Plot';                   
                         elseif startsWith(which,'custom_TraceElements')
                            if isfield(settings,'TENorm')&&isfield(settings.TENorm,'Name')
                                hfig.Name=settings.TENorm.Name;
                            else
                                hfig.Name='Trace Element Plot';
                            end
                        else
                            hfig.Name='Scatter Plot';
                        end
                        hfig.Position(3:4)=hfig.Position(3:4).*1.5;
                        hold on

                    end


                    if  startsWith(which,'custom_')
                        if isfield(settings,'InputTable') && not(isempty(settings.InputTable))
                            InputTable=settings.InputTable;
                        else
                            InputTable=app.UITable.Data;%(app.UITable.Data.Active,:);
                        end

                        if isempty(InputTable)
                            delete(app.d)
                            return
                        end
                        if startsWith(which,'custom_TraceElements') && any(endsWith(InputTable.Properties.VariableNames,{'_mat_ugg'}))
                            InputTable.Properties.VariableNames=replace(InputTable.Properties.VariableNames,{'_mat_ugg'},'');
                            if any(any(endsWith(InputTable.Properties.VariableNames,{'_incl_ugg'})))
                                message={'The data table contains both the matrix and the inclusion data! To plot the inclusion data, split the matrix and inclusion data in the "Data subdivision" tab.'};
                                uialert(app.VisModule_GUI,message,'Warning',Icon='warning');
                            end
                        end


                        % if any(endsWith(InputTable.Properties.VariableNames,'_lod'))
                        %
                        %      InputTable_below_lod=table();
                        %     lodcols=InputTable.Properties.VariableNames(endsWith(InputTable.Properties.VariableNames,'_lod'));
                        %
                        %     settings.DetectionLimits.DetectionLimitsTable=InputTable(:,InputTable.Properties.VariableNames(endsWith(InputTable.Properties.VariableNames,'_lod')));
                        %     settings.DetectionLimits.DetectionLimitsTable.Properties.VariableNames=replace(settings.DetectionLimits.DetectionLimitsTable.Properties.VariableNames,'_lod','');
                        % %    for n_lod=1:numel(lodcols)
                        % %
                        % %         vals_below_lod=table2array(InputTable(:,replace(lodcols(n_lod),'_lod','')))<table2array(InputTable(:,lodcols(n_lod)));
                        % %
                        % %         %
                        % %         if strcmp('','Plot data above LOD + LOD') % (min max mean median all)
                        % %
                        % %         elseif strcmp('','Plot all data ignoring LOD (This is dangerous!)')
                        % %             InputTable_below_lod(1:numel(vals_below_lod),replace(lodcols(n_lod),'_lod',''))={nan};
                        % %             InputTable_below_lod(vals_below_lod,replace(lodcols(n_lod),'_lod',''))= InputTable(vals_below_lod,replace(lodcols(n_lod),'_lod','_ugg'));
                        % %         end
                        % %
                        % %
                        % %         InputTable(vals_below_lod,replace(lodcols(n_lod),'_lod',''))={nan};
                        % %
                        % %      % InputTable.Properties.VariableNames=replace(InputTable.Properties.VariableNames,{'_mat_ugg'},'');
                        % %
                        % %    end
                        %
                        % end


                        T=InputTable;

                        symb=app.SymbolDropDown.Value;
                        mfc=app.MarkerFaceColorDropDown.Value;
                        u_c_f= InputTable.Active;


                        if  isfield(settings,'SubDiv') && settings.SubDiv==true && not(isempty(app.ListBox.Value))&& not(isempty(app.ListBox_2.Value)) && not(isempty(ismember(InputTable.(char(app.ListBox.Value)),app.ListBox_2.Value)))
                            T_data=InputTable.(char(app.ListBox.Value));
                            T_data(cellfun(@isempty, T_data))={' '};

                            u_t=unique(T_data(ismember(T_data,app.ListBox_2.Value)),'stable');
                            T_=T;

                            if startsWith(which,'custom_TraceElements') && isfield(settings,'DetectionLimits') && isfield(settings.DetectionLimits,'PlotDetectionLimits')  && settings.DetectionLimits.PlotDetectionLimits==1
                                T_DL_=settings.DetectionLimits.DetectionLimitsTable;
                            end


                            for k=1:numel(unique(u_t))
                                T=T_;
                                u_c_f=ismember(T_data,u_t(k))& InputTable.Active;

                                if startsWith(which,'custom_TraceElements') && isfield(settings,'DetectionLimits') && isfield(settings.DetectionLimits,'PlotDetectionLimits')  && settings.DetectionLimits.PlotDetectionLimits==1&& size(T_DL_,1)==size(T_,1)
                                    settings.DetectionLimits.DetectionLimitsTable=T_DL_(u_c_f,:);
                                    if startsWith(app.DropDown_2.Value,'Range')
                                        T_DL_{:,:}(T_DL_{:,:} ~= max(T_DL_{:,:},[],1,'omitnan') & ...
                                            T_DL_{:,:} ~= min(T_DL_{:,:},[],1,'omitnan')) = NaN;
                                    elseif startsWith(app.DropDown_2.Value,'Maximum')
                                        T_DL_{:,:}(T_DL_{:,:} ~= max(T_DL_{:,:},[],1,'omitnan')) = NaN;

                                    elseif startsWith(app.DropDown_2.Value,'Minimum')
                                        T_DL_{:,:}(T_DL_{:,:} ~= min(T_DL_{:,:},[],1,'omitnan')) = NaN;

                                    elseif startsWith(app.DropDown_2.Value,'Mean')
                                        T_DL_{:,:}(T_DL_{:,:} ~= mean(T_DL_{:,:},[],1,'omitnan')) = NaN;

                                    elseif startsWith(app.DropDown_2.Value,'Median')
                                        T_DL_{:,:}(T_DL_{:,:} ~= median(T_DL_{:,:},[],1,'omitnan')) = NaN;

                                    end

                                    if isfield(settings,'custom')&&isfield(settings.custom,'Annotation')&& settings.custom.Annotation==true
                                        settings.DetectionLimits.Annotation=true;
                                        settings.DetectionLimits.AnnotationColumn=settings.custom.AnnotationColumn;
                                    else
                                        settings.DetectionLimits.Annotation=false;
                                    end

                                    if ~endsWith(app.DropDown_2.Value,'(grey)')
                                        settings.DetectionLimits.Color=mfc;
                                    end
                                end
                                T=InputTable(u_c_f,:);

                             %   T.Properties.VariableNames=replace(T.Properties.VariableNames,{' [ppm]','_ppm_','_ppm',' (ppm)','ppm'},'');

                                settings.plot_settings.symbol=symb;
                                settings.plot_settings.MarkerFaceColor=mfc;

                                if startsWith(which,'custom_tern')
                                    [ax2plot,~]=plot_tern_data_fun(T,ax2plot,settings);
                                elseif startsWith(which,'custom_tetra')
                                    [ax2plot,~]=plot_tetra_data_fun(T,ax2plot,settings);
                                elseif startsWith(which,'custom_XYZ')
                                    [ax2plot,~]=plot_XYZ_data_fun(T,ax2plot,settings);
                                elseif startsWith(which,'custom_XY')
                                    [ax2plot,~]=plot_XY_data_fun(T,ax2plot,settings);
                                elseif startsWith(which,'custom_ratio_XYZ')
                                    [ax2plot,~]=plot_ratio_XYZ_data_fun(T,ax2plot,settings);
                                elseif startsWith(which,'custom_ratio_XY')
                                    [ax2plot,~]=plot_ratio_XY_data_fun(T,ax2plot,settings);
                                elseif startsWith(which,'custom_TraceElements')
                                    %   [ax2plot,~]=plot_TE_data_fun(T,ax2plot,settings);
                                    Update_TE_plot(app,T,ax2plot,settings)
                                end

                                %    [ax2plot,~]=plot_XYZ_data_fun(T,ax2plot,settings);
                                legend_str=[legend_str {[u_t{k}]}];%#ok
                                symb=IterateMarkerSymbol(app,symb);
                                mfc=IterateMarkerColor(app,mfc);

                            end
                        else

                            %                         if app.FilterasNaNCheckBox.Value
                            %                             T(~u_c_f,(any(cellfun(@isnumeric,table2cell(T)))))={nan};
                            %                         else
                            %                             T=T(u_c_f,:);
                            %                         end
                            settings.plot_settings.symbol=symb;
                            settings.plot_settings.MarkerFaceColor=mfc;
                            if startsWith(which,'custom_tern')
                                [ax2plot,~]=plot_tern_data_fun(T(u_c_f,:),ax2plot,settings);
                            elseif startsWith(which,'custom_tetra')
                                [ax2plot,~]=plot_tetra_data_fun(T(u_c_f,:),ax2plot,settings);
                            elseif startsWith(which,'custom_XYZ')
                                [ax2plot,~]=plot_XYZ_data_fun(T(u_c_f,:),ax2plot,settings);
                            elseif startsWith(which,'custom_XY')
                                [ax2plot,~]=plot_XY_data_fun(T(u_c_f,:),ax2plot,settings);
                            elseif startsWith(which,'custom_TraceElements')
                                % [ax2plot,~]=plot_TE_data_fun(T(u_c_f,:),ax2plot,settings);
                                Update_TE_plot(app,T(u_c_f,:),ax2plot,settings)
                            end
                            try
                                legend_str=[legend_str settings.title];
                            catch
                            end

                            if isempty(legend_str)
                                legend_str='data';
                            end
                        end

                        if app.DisplayFilteredCheckBox.Value && not(isempty(InputTable(~InputTable.Active,:)))

                            %h=plot(,'Color','r','Marker','x','MarkerFaceColor','r','MarkerEdgeColor','r','LineStyle','none','LineWidth',app.LineWidthSpinner.Value,'MarkerSize',app.MarkerSizeSpinner.Value);

                            settings.plot_settings.symbol='x';
                            settings.plot_settings.MarkerEdgeColor=[1 0 0];
                            settings.plot_settings.MarkerLineWidth.Value=2.5;
                            u_c_f= InputTable.Active;

                            if startsWith(which,'custom_tern')
                                [ax2plot,~]=plot_tern_data_fun(InputTable(~u_c_f,:),ax2plot,settings);
                            elseif startsWith(which,'custom_tetra')
                                [ax2plot,~]=plot_tetra_data_fun(InputTable(~u_c_f,:),ax2plot,settings);
                            elseif startsWith(which,'custom_XYZ')
                                [ax2plot,~]=plot_XYZ_data_fun(InputTable(~u_c_f,:),ax2plot,settings);
                            elseif startsWith(which,'custom_XY')
                                [ax2plot,~]=plot_XY_data_fun(InputTable(~u_c_f,:),ax2plot,settings);
                            elseif startsWith(which,'custom_TraceElements')
                                %[ax2plot,~]=plot_TE_data_fun(InputTable(~u_c_f,:),ax2plot,settings);
                                Update_TE_plot(app,InputTable(~u_c_f,:),ax2plot,settings)
                            end
                            legend_str=[legend_str {'Filtered'}];
                        end
                    else

                        vars2plot={app.VarSelectionTree.CheckedNodes.Text};

                        if isfield(settings,'InputTable') && not(isempty(settings.InputTable))
                            InputTable=settings.InputTable;
                        else

                            InputTable=app.UITable.Data;
                        end


                        if  not(ismember(app.XAxesVariableDropDown.Value,InputTable.Properties.VariableNames))
                            x=1:size(InputTable,1);
                        else
                            x=InputTable.(char(app.XAxesVariableDropDown.Value));
                        end

                        if app.UseDataSubdivisionCheckBox.Value==true && not(isempty(app.ListBox.Value)) %%
                            T_data=InputTable.(char(app.ListBox.Value));
                            T_data(cellfun(@isempty, T_data))={' '};
                            u_t=unique(T_data(ismember(T_data,app.ListBox_2.Value)),'stable');
                        else
                            T_data.UseDataSubdivisionCheckBox=false;
                            u_t='';
                        end

                        mfa=.8;
                        mea=.8;
                        mfc=app.MarkerFaceColorDropDown.Value;
                        mec=app.MarkerEdgeColorDropDown.Value;
                        symb=app.SymbolDropDown.Value;

                        if strcmp(app.AppearanceDropDown.Value,'Color Symbol')

                            for n=1:numel(vars2plot)
                                y=InputTable.(char(vars2plot(n)));
                                u_c_f=InputTable.Active;


                                if  isfield(settings,'SubDiv') && settings.SubDiv==true && not(isempty(app.ListBox_2.Value)) && not(isempty(ismember(T_data,app.ListBox_2.Value)))
                                    for k=1:numel(unique(u_t))

                                        u_c_f=ismember(T_data,u_t(k))& InputTable.Active;
                                        if app.FilterasNaNCheckBox.Value
                                            x_plot=x;
                                            y_plot=y;
                                            x_plot(~u_c_f)=nan;
                                            y_plot(~u_c_f)=nan;
                                        else
                                            x_plot=x(u_c_f);
                                            y_plot=y(u_c_f);
                                        end


                                        if startsWith(which,'Profile_Plot')
                                            plot(ax2plot,x_plot,y_plot,'Color',[mfc LineAlpha],'Marker',symb,'MarkerFaceColor',mfc,'MarkerEdgeColor',mec,'LineStyle',app.LineStyleDropDown.Value,'LineWidth',app.LineWidthSpinner.Value,'MarkerSize',app.MarkerSizeSpinner.Value,'Tag',[vars2plot{n} '_' u_t{k}]);
                                        else
                                            scatter(ax2plot,x_plot,y_plot,app.MarkerSizeSpinner.Value*10,symb,'filled','MarkerFaceAlpha',mfa,'MarkerEdgeAlpha',mea,'MarkerEdgeColor',mec,'MarkerFaceColor',mfc)
                                        end


                                        legend_str=[legend_str {[vars2plot{n} '_' u_t{k}]}];%#ok
                                        symb=IterateMarkerSymbol(app,symb);
                                        %
                                    end
                                    symb=app.SymbolDropDown.Value;

                                else
                                    if app.FilterasNaNCheckBox.Value
                                        x_plot=x;
                                        y_plot=y;
                                        x_plot(~u_c_f)=nan;
                                        y_plot(~u_c_f)=nan;
                                    else
                                        x_plot=x(u_c_f);
                                        y_plot=y(u_c_f);
                                    end


                                    if startsWith(which,'Profile_Plot')
                                        plot(ax2plot,x_plot,y_plot,'Color',[mfc LineAlpha],'Marker',symb,'MarkerFaceColor',mfc,'MarkerEdgeColor',mec,'LineStyle',app.LineStyleDropDown.Value,'LineWidth',app.LineWidthSpinner.Value,'MarkerSize',app.MarkerSizeSpinner.Value,'Tag',vars2plot{n});
                                    else
                                        scatter(ax2plot,x_plot,y_plot,app.MarkerSizeSpinner.Value*10,symb,'filled','MarkerFaceAlpha',mfa,'MarkerEdgeAlpha',mea,'MarkerEdgeColor',mec,'MarkerFaceColor',mfc)
                                    end

                                    legend_str=[legend_str {vars2plot{n}}];%#ok
                                    symb=IterateMarkerSymbol(app,symb);

                                end

                                if app.DisplayFilteredCheckBox.Value && not(isempty(x(~InputTable.Active)))
                                    if startsWith(which,'Profile_Plot')
                                        plot(ax2plot,x(~InputTable.Active),y(~InputTable.Active),'Color',[mfc LineAlpha],'Marker','x','MarkerFaceColor','r','MarkerEdgeColor','r','LineStyle','none','LineWidth',2,'MarkerSize',app.MarkerSizeSpinner.Value,'Tag','filtered');
                                    else
                                        scatter(ax2plot,x(~InputTable.Active),y(~InputTable.Active),app.MarkerSizeSpinner.Value*10,'x','filled','MarkerFaceAlpha',mfa,'MarkerEdgeAlpha',mea,'MarkerEdgeColor','r','MarkerFaceColor','r','LineWidth',2)                    %h=plot(ax2plot,x(~InputTable.Active),y(~InputTable.Active),'Color','r','Marker','x','MarkerFaceColor','r','MarkerEdgeColor','r','LineStyle','none','LineWidth',app.LineWidthSpinner.Value,'MarkerSize',app.MarkerSizeSpinner.Value);
                                    end
                                    legend_str=[legend_str {[vars2plot{n} ' Filtered']}];%#ok
                                end
                                mfc=IterateMarkerColor(app,mfc);
                                mec=IterateMarkerColor(app,mec);

                            end

                        else

                            symb=app.SymbolDropDown.Value;
                            u_c_f= InputTable.Active;

                            for n=1:numel(vars2plot)

                                y=InputTable.(char(vars2plot(n)));


                                if  isfield(settings,'SubDiv') && settings.SubDiv==true && not(isempty(app.ListBox_2.Value)) && not(isempty(ismember(T_data,app.ListBox_2.Value)))
                                    %isfield(settings,'SubDiv') && settings.SubDiv==true
                                    for k=1:numel(unique(u_t))
                                        u_c_f=ismember(T_data,u_t(k))& InputTable.Active;
                                        if app.FilterasNaNCheckBox.Value
                                            x_plot=x;
                                            y_plot=y;
                                            x_plot(~u_c_f)=nan;
                                            y_plot(~u_c_f)=nan;
                                        else
                                            x_plot=x(u_c_f);
                                            y_plot=y(u_c_f);
                                        end

                                        if startsWith(which,'Profile_Plot')
                                            plot(ax2plot,x_plot,y_plot,'Color',[mfc LineAlpha],'Marker',symb,'MarkerFaceColor',mfc,'MarkerEdgeColor',mec,'LineStyle',app.LineStyleDropDown.Value,'LineWidth',app.LineWidthSpinner.Value,'MarkerSize',app.MarkerSizeSpinner.Value,'Tag',vars2plot{n});
                                        else
                                            scatter(ax2plot,x_plot,y_plot,app.MarkerSizeSpinner.Value*10,symb,'filled','MarkerFaceAlpha',mfa,'MarkerEdgeAlpha',mea,'MarkerEdgeColor',mec,'MarkerFaceColor',mfc)
                                        end
                                        legend_str=[legend_str {[vars2plot{n} '_' u_t{k}]}];%#ok
                                        mfc=IterateMarkerColor(app,mfc);
                                    end
                                    symb=IterateMarkerSymbol(app,symb);
                                else

                                    if app.FilterasNaNCheckBox.Value
                                        x_plot=x;
                                        y_plot=y;
                                        x_plot(~u_c_f)=nan;
                                        y_plot(~u_c_f)=nan;
                                    else
                                        x_plot=x(u_c_f);
                                        y_plot=y(u_c_f);
                                    end
                                    if startsWith(which,'Profile_Plot')
                                        plot(ax2plot,x_plot,y_plot,'Color',[mfc LineAlpha],'Marker',symb,'MarkerFaceColor',app.MarkerFaceColorDropDown.ItemsData{n},'MarkerEdgeColor',app.MarkerEdgeColorDropDown.ItemsData{n},'LineStyle',app.LineStyleDropDown.Value,'LineWidth',app.LineWidthSpinner.Value,'MarkerSize',app.MarkerSizeSpinner.Value,'Tag',vars2plot{n});
                                    else
                                        scatter(ax2plot,x_plot,y_plot,app.MarkerSizeSpinner.Value*10,symb,'filled','MarkerFaceAlpha',mfa,'MarkerEdgeAlpha',mea,'MarkerEdgeColor',app.MarkerEdgeColorDropDown.ItemsData{n},'MarkerFaceColor',app.MarkerFaceColorDropDown.ItemsData{n})
                                    end

                                    legend_str=[legend_str {vars2plot{n}}];%#ok
                                    symb=IterateMarkerSymbol(app,symb);
                                end
                                if app.DisplayFilteredCheckBox.Value && not(isempty(x(~InputTable.Active)))
                                    if startsWith(which,'Profile_Plot')
                                        plot(ax2plot,x(~InputTable.Active),y(~InputTable.Active),'Color','r','Marker','x','MarkerFaceColor','r','MarkerEdgeColor','r','LineStyle','none','LineWidth',2,'MarkerSize',app.MarkerSizeSpinner.Value,'Tag','Filtered');
                                    else
                                        scatter(ax2plot,x(~InputTable.Active),y(~InputTable.Active),app.MarkerSizeSpinner.Value*10,'x','filled','MarkerFaceAlpha',mfa,'MarkerEdgeAlpha',mea,'MarkerEdgeColor','r','MarkerFaceColor','r','LineWidth',2)                    %h=plot(ax2plot,x(~InputTable.Active),y(~InputTable.Active),'Color','r','Marker','x','MarkerFaceColor','r','MarkerEdgeColor','r','LineStyle','none','LineWidth',app.LineWidthSpinner.Value,'MarkerSize',app.MarkerSizeSpinner.Value);
                                    end
                                    legend_str=[legend_str {[vars2plot{n} ' Filtered']}];%#ok
                                end
                            end
                        end


                        if isfield(settings,'title')
                            title(ax2plot,settings.title,'Rotation',0,'FontWeight','bold','Color','k','Interpreter','none');
                        end
                        pbaspect(ax2plot,'auto');

                        if app.CustomAxesLabelsCheckBox.Value
                            xlabel(ax2plot,app.CustomXLabelEditField.Value,'interpreter',app.LabelInterpreterDropDown.Value)
                            ylabel(ax2plot,app.CustomYLabelEditField.Value,'interpreter',app.LabelInterpreterDropDown.Value)
                        else
                            xlabel(ax2plot,app.XAxesVariableDropDown.Value,'interpreter',app.LabelInterpreterDropDown.Value)
                        end

                        try
                            if not(isempty(x(InputTable.Active))) && min(x(InputTable.Active))< max(x(InputTable.Active)) && app.DisplayFilteredCheckBox.Value && not(isempty(x(~InputTable.Active)))
                                ax2plot.XLim=[min(x) max(x)];
                            elseif not(isempty(x(InputTable.Active))) && min(x(InputTable.Active))< max(x(InputTable.Active))
                                ax2plot.XLim=[min(x(InputTable.Active)) max(x(InputTable.Active))];
                            end

                        catch ME
                            DisplayError(app, ME,'XLim Update Error')
                        end


                        drawnow

                        settings.type.vars2plot=vars2plot;

                    end

                    if startsWith(which,'custom_TraceElements')

                        %%%%

                        lines = flipud(findall(ax2plot, 'Type', 'line'));

                        % Get unique tags
                        tags = get(lines, 'Tag');
                        if ischar(tags)
                            tags = {tags};  % convert single char to cell array
                        end


                        % Filter out empty tags
                        nonEmptyMask = ~cellfun(@isempty, tags);
                        lines = lines(nonEmptyMask);
                        tags = tags(nonEmptyMask);

                        % Get unique tags and group lines
                        [~, ~, tagIdx] = unique(tags, 'stable');

                        % Get first occurrence of each tag
                        representativeLines = lines(accumarray(tagIdx(:), (1:length(tagIdx))', [], @min));
                        if not(iscell(legend_str))
                            legend_str={legend_str};
                        end


                        for i = 1:numel(representativeLines)
                            % Update all Tags with the new LegendString
                            if numel(representativeLines)==numel(legend_str)
                                set(lines(strcmp(tags, representativeLines(i).Tag)), 'Tag', legend_str{i}, 'DisplayName', legend_str{i});
                            elseif numel(representativeLines)==numel(legend_str)*2
                                legIdx = ceil(i/2);
                                if endsWith(representativeLines(i).Tag,'LOD')
                                    set(lines(strcmp(tags, representativeLines(i).Tag)),'Tag', [legend_str{legIdx}, ' LOD'], 'DisplayName', [legend_str{legIdx}, ' LOD']);
                                else
                                    set(lines(strcmp(tags, representativeLines(i).Tag)),'Tag', legend_str{legIdx}, 'DisplayName', legend_str{legIdx});
                                end

                                if i==numel(representativeLines)
                                    legend_str  = unique({lines.Tag}, 'stable');
                                end
                            end

                        end

                        legend(ax2plot,(representativeLines),[existing_objects legend_str ],'interpreter','none','location','bestoutside') % ATH check if flipud


                        %%%%
                    else
                        legend(ax2plot,[existing_objects legend_str],'interpreter','none')

                    end

                    settings.type.Value=which;
                    settings.type.description=which;

                    if isfield(settings,'FigureName')
                        FigureName=settings.FigureName;
                       hfig.Name=FigureName;
                    else
                        FigureName=which;
                    end

                    ax2plot.FontSize=app.FigureFontSizeSpinner.Value;


                    if exist('hfig','var')
                        try
                            if isfield(app.MinPlotX,'Standalone') && app.MinPlotX.Standalone
                                EPM=EasyPlotModule(app.MinPlotX,app);

                                EPM.MinPlotXEasyPlot_public_update(app.MinPlotX.MinPlotXFigures);
                            else

                                callEasyPlotModule(app.MinPlotX)
                            end
                        catch ME
                            DisplayError(app, ME)
                        end

                        app.MinPlotX.MinPlotXFigures.hFig(hFig_id).options=settings;
                        app.MinPlotX.MinPlotXFigures.hFig_type{hFig_id}=which;
                        app.MinPlotX.MinPlotXFigures.hFig_description{hFig_id}=FigureName;
                        app.MinPlotX.MinPlotXFigures.hFig(hFig_id).ax2plot=ax2plot;

                        CenterFig_fun(hfig)
                        hfig.Visible=true;

                        try
                            if isfield(app.MinPlotX,'Standalone') && app.MinPlotX.Standalone
                                EPM=EasyPlotModule(app.MinPlotX,app);

                                EPM.MinPlotXEasyPlot_public_update(app.MinPlotX.MinPlotXFigures);
                            else

                                callEasyPlotModule(app.MinPlotX)
                            end
                        catch ME
                            DisplayError(app, ME)
end


                    end
                    %                     try
                    %                     callEasyPlotModule(app.MinPlotX)
                    %                     catch
                    %                     end
                    % end
                end
            catch ME
        if ~isnumeric(app.d)
            delete(app.d)
        end
        DisplayError(app, ME,'VisModule send to EasyPlot')
                return
            end
            if ~isnumeric(app.d)
            delete(app.d)
            end
        end

        function ReadColorMaps(app)
            if isdeployed
                XMap_ColorMaps_path=fullfile(app.MinPlotX.installpath,'Dev','XMT','XMap_ColorMaps.txt');
            else
                XMap_ColorMaps_path=which(fullfile('Dev','XMT','XMap_ColorMaps.txt'));

                if isempty(XMap_ColorMaps_path)
                    XMap_ColorMaps_path=which('XMap_ColorMaps.txt');
                end
            end

            fid = fopen(XMap_ColorMaps_path,'r');

            Compt = 0;

            app.ColorMaps(1).Name = 'None';
            app.ColorMaps(1).Code = 0;

            while 1
                tline = fgetl(fid);

                if isequal(tline,-1)
                    break
                end

                if length(tline) >= 1
                    if isequal(tline(1),'>')
                        Compt = Compt+1;
                        app.ColorMaps(Compt).Name = tline(3:end);
                        Row = 0;
                        while 1
                            tline = fgetl(fid);
                            if isequal(tline,-1) || isequal(tline,'')
                                break
                            end
                            NUM = strread(tline,'%f'); %# ok
                            Row = Row+1;
                            app.ColorMaps(Compt).Code(Row,1:3) = NUM(1:3);
                        end

                    end
                end
            end
            fclose(fid);

            % Update the menu
            Items=cell(1,numel(app.ColorMaps));
            for i = 1:numel(app.ColorMaps)
                Items{i} = app.ColorMaps(i).Name;
            end
            app.Options_ColormapDropDown.Items =  Items; %extractfield(ColorMaps,'Name');

        end

        function [ColorMap] = PlotMap_UpdateColormap(app,Resolution,~)
            if not(exist('Resolution','var')) || isempty(Resolution)
                Resolution=app.Options_ColormapResEditField.Value;
            end


            SelColorMap = find(ismember(app.Options_ColormapDropDown.Items,app.Options_ColormapDropDown.Value));
            if app.Options_InvertColormap.Value
                ColorData =app.ColorMaps(SelColorMap).Code;
            else
                ColorData =flip(app.ColorMaps(SelColorMap).Code);
            end
            if Resolution==1
                ColorMap = [0,0,0];
            else
                Step = (Resolution-1)/(size(ColorData,1)-1);
                X = 1:Step:Resolution;


                ColorMap = zeros(length(1:Resolution),size(ColorData,2));
                for i = 1:size(ColorData,2)
                    ColorMap(:,i) = interp1(X',ColorData(:,i),1:Resolution);
                end

                % upper & lower
                if app.Options_LowerCheckBox.Value
                    if isequal(app.Options_LowerColor.Value,'Black')
                        ColorMap(1,:) = [0,0,0];
                    else
                        ColorMap(1,:) = [1,1,1];
                    end
                end
                if app.Options_UpperCheckBox.Value
                    if isequal(app.Options_UpperColor.Value,'Black')
                        ColorMap(end,:) = [0,0,0];
                    else
                        ColorMap(end,:) = [1,1,1];
                    end
                end
            end
            %log
            %linlogchange start

            %  app.ColorScale = 'linear';

            if not(exist('no_update','var'))
                app.ColorMapValues = ColorMap;
            end
        end

        function ReadTEData(app,file)
            if not(exist("file","var"))
                app.SpiderData=struct();
                if isdeployed
                    file=fullfile(app.MinPlotX.installpath,'Dev','XMT','XMap_SpiderNormalizations.txt');
                else
                    file=which(fullfile('Dev','XMT','XMap_SpiderNormalizations.txt'));
                    if isempty(file)
                        file=which('XMap_SpiderNormalizations.txt');
                    end
                end
            end

            fid = fopen(file,'r');
            Compt = 0;

            if isfield(app.SpiderData,'Names') && not(isempty(app.SpiderData.Names))
                Compt=numel(app.SpiderData.Names);
            else
                app.SpiderData.Names = {};
                app.SpiderData.Data(1).El = 'None';
                app.SpiderData.Data(1).Conc = 0;
            end

            %  ErrorLoad = 0;
            while 1
                tline = fgetl(fid);

                if isequal(tline,-1)
                    break
                end

                if length(tline) >= 1
                    if isequal(tline(1),'>')
                        Str1 = tline(3:end);
                        Str2 = strread(fgetl(fid),'%s');
                        Str3 = strread(fgetl(fid),'%s');

                        if isequal(length(Str2),length(Str3))
                            Compt = Compt+1;
                            app.SpiderData.Names{Compt} = Str1;
                            app.SpiderData.Data(Compt).El = Str2;
                            Int3 = [];
                            for i=1:length(Str3)
                                Int3(i) = str2num(Str3{i});
                            end
                            app.SpiderData.Data(Compt).Conc = Int3;

                        else
                            %  ErrorLoad = 1;
                            break
                        end
                    end
                end

            end


            T2=array2table(nan(numel(app.SpiderData.Names),numel(app.ElementList)),'VariableNames',app.ElementList);

            T=table();
            T.Name=app.SpiderData.Names';

            T_out=[T T2];

            for n=1:size(T,1)
                for m=1:numel(app.SpiderData.Data(n).El)
                    T_out(n,ismember(T_out.Properties.VariableNames,app.SpiderData.Data(n).El{m}))={app.SpiderData.Data(n).Conc(m)};
                end
            end

            app.NormalisetoDropDown.Items=app.SpiderData.Names;
        end

        function Update_TE_plot(app,T_input,ax2plot,options)

            if not(exist('options','var'))
                [options]=plot_TE_fun([],[]);
            end


            dataid=find(strcmp(app.SpiderData.Names,app.NormalisetoDropDown.Value));

            %app.DefaultsDropDown.Value='Custom'

            if not(isfield(options,'Element_List'))
                options.Element_List=app.SpiderData.Data(dataid).El;
                options.TENorm.NormState=true;
                options.TENorm.Values=app.SpiderData.Data(dataid).Conc;
                options.TENorm.Elements=app.SpiderData.Data(dataid).El;
                options.TENorm.Name=app.NormalisetoDropDown.Value;
            else
                %  ax2plot.Title.String='';

            end

            try
                ax2plot.YaxisLabel.String=options.TENorm.Name;
            catch

            end


            if not(isempty(T_input))
                T_plot=T_input;
                VariableNames={};
                try
                    if isfield(options,'TENorm') && isfield(options.TENorm,'Elements') && ~any(contains(options.TENorm.Elements,app.MassList)) && sum(contains(T_input.Properties.VariableNames,app.MassList))>2

                        VariableNames=T_input.Properties.VariableNames;
                        VariableNames(contains(T_input.Properties.VariableNames,app.MassList))= replace(VariableNames(contains(T_input.Properties.VariableNames,app.MassList)),{'0','1','2','3','4','5','6','7','8','9','_mat_ugg'},'');
                        if ~isequal(numel(unique(VariableNames)),numel(VariableNames))
                            VariableNames=flip(matlab.lang.makeUniqueStrings(flip(VariableNames)));
                        end

                        if not(isempty(VariableNames))
                            T_plot.Properties.VariableNames=VariableNames;
                        end
                    end

                    plot_TE_data_fun(T_plot,ax2plot,options);
                    % ax2plot.Visible=true;
                catch ME
                    keyboard

                end
            end


        end


        function [T_new,T_DL] = FindDetection_Limits(app,T)
            T_Cell=table2cell(T);
            T_Cell_DL=T_Cell;
            mask = cellfun(@(x) ~isnumeric(x) && ischar(x) && contains(x, '<'), T_Cell);
            % [row, col] = find(mask);

            %T_Cell_DL(row, col)=cellfun(@str2double,cellfun(@(x) replace(x,'<',''), T_Cell(row, col));
            %T_Cell_DL(not(row),not(col))=nan;

            T_Cell_DL(not(mask))={nan};
            T_Cell_DL(mask) = cellfun(@(x) str2double(strrep(x, '<', '')), T_Cell(mask), 'UniformOutput', false);

            T_DL = array2table(cell2mat(T_Cell_DL), 'VariableNames', T.Properties.VariableNames);

            T_Cell(mask)={nan};
            mask = cellfun(@ischar, T_Cell);
            T_Cell(mask) = cellfun(@str2double, T_Cell(mask), 'UniformOutput', false);
            T_new = array2table(cell2mat(T_Cell), 'VariableNames', T.Properties.VariableNames);
        end


        function [T_new, T_DL] = FindDetection_Limits2(app, T)
            tic
            try
                % Combine MassList and ElementList into one list of valid prefixes
                PrefixList = [app.MassList, app.ElementList];

                % Get variable names
                varNames = T.Properties.VariableNames;

                % Find target columns using vectorized `startsWith


                if any(endsWith(varNames,'mat_ugg'))
                    suffixes = {'_mat_ugg', '_incl_ugg', '_mix_ugg'};

                    C_out = cellfun(@(s) strcat(s, suffixes), PrefixList, 'UniformOutput', false);
                    C_out = vertcat(C_out{:});
                    PrefixList = C_out(:);
                end

                prefixMask = ismember(varNames, PrefixList');
                targetCols = find(any(prefixMask, 1));

                % Convert table to cell for manipulation
                T_Cell = table2cell(T);
                T_Cell_DL = T_Cell;

                for col = targetCols
                    colData = T_Cell(:, col);
                    isStr = find(cellfun(@ischar, colData));
                    strVals = colData(isStr);

                    % Find strings containing '<'
                    ltMask = contains(strVals, '<');

                    cleanedVals = repmat({NaN}, size(strVals));
                    T_Cell_DL(cellfun(@isnumeric, colData), col) ={NaN};

                    if isempty(ltMask)
                        continue
                    end

                    % Strip '<' and convert
                    cleanedVals(ltMask) = cellfun(@(x) str2double(strrep(x, '<', '')), strVals(ltMask), 'UniformOutput', false);

                    % Replace original values
                    colData(isStr) = cellfun(@str2double, strVals, 'UniformOutput', false); % valid numerics or NaNs


                    colData(isStr(ltMask)) = {NaN}; % treat '<' values as NaN in clean table
                    T_Cell(:, col) = colData;

                    T_Cell_DL(cellfun(@isnumeric, colData), col) ={NaN};

                    % Detection limit table: keep parsed numbers only
                    if numel(cleanedVals(ltMask))==numel(T_Cell_DL(isStr(ltMask), col))

                        T_Cell_DL(isStr(ltMask), col) = cleanedVals(ltMask);

                    else
                        T_Cell_DL(isStr(ltMask), col) = cleanedVals;
                    end

                end
                T_Cell_DL=T_Cell_DL(:,prefixMask);

                % Convert cells back to tables
                T_new = cell2table(T_Cell, 'VariableNames', varNames);
                T_DL  = cell2table(T_Cell_DL, 'VariableNames', varNames(prefixMask));
            catch ME
                keyboard
            end
            toc
        end


        function T=combine_table_fun(app,T1,T2)
            if ~numel([T1.Properties.VariableNames T2.Properties.VariableNames])==numel(unique([T1.Properties.VariableNames T2.Properties.VariableNames]))
                newnamesT2=matlab.lang.makeUniqueStrings([T1.Properties.VariableNames T2.Properties.VariableNames]);
                T2.Properties.VariableNames=newnamesT2(numel(T1.Properties.VariableNames)+1:end);
            end
            T=[T1,T2];
        end

        function update_class_table(app)
            if isempty(char(app.VariableDropDown.Value))
                app.ClassTable.Data=[];
                app.ClassTable.ColumnName={};
                return
            end
            if app.ExcludeEmptyCheckBox.Value==true
                T_class_table_update=app.UITable.Data(app.UITable.Data.Active==true,:);
            else
                T_class_table_update=app.UITable.Data;
            end
            value = app.MethodDropDown.Value;
            sort_option="stable";
            switch value
                case 'Unique String'
                    try
                        className=unique(T_class_table_update.(char(app.VariableDropDown.Value)),sort_option);
                    catch
                        thx=T_class_table_update.(char(app.VariableDropDown.Value));
                        [thx(cellfun(@isempty,T_class_table_update.(char(app.VariableDropDown.Value))))]={' '};
                        T_class_table_update.(char(app.VariableDropDown.Value))=thx;
                        className=unique(T_class_table_update.(char(app.VariableDropDown.Value)),sort_option);

                    end

                    Value=[1:numel(className)]';
                    try
                        if isequal(className,app.ClassTable.Data.Name)
                            Active= app.ClassTable.Data.Active;
                        else
                            Active=true(numel(unique(T_class_table_update.(char(app.VariableDropDown.Value)))),1);
                        end
                    catch
                        Active=true(numel(unique(T_class_table_update.(char(app.VariableDropDown.Value)))),1);
                    end
                    app.ClassTable.Visible=true;

                    where= string(T_class_table_update.(char(app.VariableDropDown.Value)));
                    nvalues=zeros(size(Value));

                    for n=1:numel(className)

                        nvalues(n)= sum(ismember(where(find(T_class_table_update.Active)),className(n)));
                    end

                case 'Numeric'
                    className=string(unique(T_class_table_update.(char(app.VariableDropDown.Value))));
                    Value=unique(T_class_table_update.(char(app.VariableDropDown.Value)));

                    if any(isnan(Value))
                        className(isnan(Value))=[];
                        Value(isnan(Value))=[];
                        disp('NaN values ignored')
                    end

                    Active=true(size(Value));

                    app.ClassTable.Visible=true;

                    nvalues=zeros(size(Value));
                    where= T_class_table_update.(char(app.VariableDropDown.Value));
                    for n=1:numel(Value)
                        nvalues(n)= sum(ismember(where(T_class_table_update.Active),Value(n)));
                    end


                case 'NumClasses'
                    app.NumClassesPanel.Visible=true;
                    app.VariableDropDown.Visible=false;
                    app.ClassTable.Visible=false;


                    try
                        mapnr=1;
                        ClassMapNr=find(app.Data.Classes(mapnr).n_classes==app.ClassesSpinner.Value);
                        if isempty(ClassMapNr)
                            app.MaxClassesSpinner.Value=app.ClassesSpinner.Value;
                            app.MinClassesSpinner.Value=2;
                            RunClassTestButtonPushed(app)
                            ClassMapNr=find(app.Data.Classes(mapnr).n_classes==app.ClassesSpinner.Value);
                        end
                        classNumber=app.Data.Classes(mapnr).ClassMap(ClassMapNr).classNumber;
                        className=app.Data.Classes(mapnr).ClassMap(ClassMapNr).ClassesNames;
                    catch
                        if not(isfield(app.Data,'Classes'))
                            return
                        end
                        app.MaxClassesSpinner.Value=app.ClassesSpinner.Value;
                        app.MinClassesSpinner.Value=2;
                        RunClassTestButtonPushed(app)
                        ClassMapNr=find(app.Data.Classes(mapnr).n_classes==app.ClassesSpinner.Value);
                        classNumber=app.Data.Classes(mapnr).ClassMap(ClassMapNr).classNumber;
                        className=app.Data.Classes(mapnr).ClassMap(ClassMapNr).ClassesNames;
                    end


                    Value=sort(unique(classNumber(not(isnan(classNumber)))));
                    nvalues=zeros(size(Value));

                    if not(isempty(app.ClassTable.Data))&& isequal(className,app.ClassTable.Data.Name)
                        Active= app.ClassTable.Data.Active;
                    else
                        Active=true(size(Value));

                    end


                    for n=1:numel(unique(classNumber(isfinite(classNumber))))
                        nvalues(n)=sum(classNumber(isfinite(classNumber))==n);
                    end

            end


            T=table(Active,className,Value,nvalues);
            T.Properties.VariableNames={'Active','Name','Value','n'};

            app.ClassTable.Data=T;
            app.ClassTable.ColumnName=T.Properties.VariableNames;
        end

        function T=split_table_fun(app,baseData)
            % -------- Inclusion --------
            InputTable_incl = baseData(:, ...
                [app.ListBox.Value ...
                baseData.Properties.VariableNames( ...
                endsWith(baseData.Properties.VariableNames, ...
                {'_incl_ugg','_incl_lod'}))]);

            InputTable_incl.Properties.VariableNames = ...
                replace(InputTable_incl.Properties.VariableNames, '_incl_ugg', '');
            InputTable_incl.Properties.VariableNames = ...
                replace(InputTable_incl.Properties.VariableNames, '_incl_lod', '_lod');

            InputTable_incl.(app.ListBox.Value) = cellfun( ...
                @(x)[x ' Inclusion'], ...
                InputTable_incl.(app.ListBox.Value), ...
                'UniformOutput', false);

            % -------- Matrix --------
            InputTable_mat = baseData(:, ...
                [app.ListBox.Value ...
                baseData.Properties.VariableNames( ...
                endsWith(baseData.Properties.VariableNames, ...
                {'_mat_ugg','_mat_lod'}))]);

            InputTable_mat.Properties.VariableNames = ...
                replace(InputTable_mat.Properties.VariableNames, '_mat_ugg', '');
            InputTable_mat.Properties.VariableNames = ...
                replace(InputTable_mat.Properties.VariableNames, '_mat_lod', '_lod');

            InputTable_mat.(app.ListBox.Value) = cellfun( ...
                @(x)[x ' Matrix'], ...
                InputTable_mat.(app.ListBox.Value), ...
                'UniformOutput', false);

            % -------- Mix --------
            InputTable_mix = baseData(:, ...
                [app.ListBox.Value ...
                baseData.Properties.VariableNames( ...
                endsWith(baseData.Properties.VariableNames, ...
                {'_mix_ugg','_incl_lod'}))]);

            InputTable_mix.Properties.VariableNames = ...
                replace(InputTable_mix.Properties.VariableNames, '_mix_ugg', '');
            InputTable_mix.Properties.VariableNames = ...
                replace(InputTable_mix.Properties.VariableNames, '_incl_lod', '_lod');

            InputTable_mix.(app.ListBox.Value) = cellfun( ...
                @(x)[x ' Mix'], ...
                InputTable_mix.(app.ListBox.Value), ...
                'UniformOutput', false);

            % -------- Base columns --------
            baseCols = ~endsWith( ...
                baseData.Properties.VariableNames, ...
                {'_incl_ugg','_mat_ugg','_mix_ugg','_lod', app.ListBox.Value});

            % -------- Merge --------
            T_mat  = [baseData(:, baseCols) InputTable_mat];
            T_incl = [baseData(:, baseCols) InputTable_incl];
            T_mix  = [baseData(:, baseCols) InputTable_mix];

            n = height(T_mat);
            idx = reshape(repmat(1:n,3,1),1,[]);
            T = T_mat(idx,:);
            T(2:3:end,:) = T_incl;

            if isequal(size(T_incl),size(T_mix))
                T(3:3:end,:) = T_mix;
            else
                T(3:3:end,:) = [];
            end

        end


        %                 function update_diagram_tree(app,Tree2Update)
        % if not(exist('Tree2Update','var'))
        %                 Tree2Update=app.DiagramTree;
        % end
        %
        %             % Assume 'app.Tree' is your uitree component
        %             % Clear any existing nodes
        %             ResetTree(app,Tree2Update);
        %
        %             % Level order: Category -> Subcategory -> DataType -> Diagram
        %             categories = unique(app.DiagramsDefinitionTable.Category, 'stable');
        %
        %             for iCat = 1:numel(categories)
        %                 catName = categories{iCat};
        %
        %                 % Create Category node
        %                 catNode = uitreenode(Tree2Update, 'Text', catName);
        %                 catNode.NodeData = struct(...
        %                     'Level', 'Category', ...
        %                     'Name', catName, ...
        %                     'Diagrams', [] ...
        %                     );
        %
        %                 % Subcategories within this Category
        %                 subcats = unique(app.DiagramsDefinitionTable.Subcategory(strcmp(app.DiagramsDefinitionTable.Category, catName)), 'stable');
        %                 for iSub = 1:numel(subcats)
        %                     subName = subcats{iSub};
        %                     subNode = uitreenode(catNode, 'Text', subName);
        %                     subNode.NodeData = struct(...
        %                         'Level', 'Subcategory', ...
        %                         'Name', subName, ...
        %                         'Diagrams', [] ...
        %                         );
        %
        %                     % DataTypes within this Subcategory
        %                     dtRows = strcmp(app.DiagramsDefinitionTable.Category, catName) & strcmp(app.DiagramsDefinitionTable.Subcategory, subName);
        %                     dataTypes = unique(app.DiagramsDefinitionTable.DataType(dtRows), 'stable');
        %
        %                     for iDT = 1:numel(dataTypes)
        %                         dtName = dataTypes{iDT};
        %                         dtNode = uitreenode(subNode, 'Text', dtName);
        %                         dtNode.NodeData = struct(...
        %                             'Level', 'DataType', ...
        %                             'Name', dtName, ...
        %                             'Diagrams', [] ...
        %                             );
        %
        %                         % All diagrams in this combination
        %                         diagramRows = dtRows & strcmp(app.DiagramsDefinitionTable.DataType, dtName);
        %                         diagramNames = app.DiagramsDefinitionTable.Name(diagramRows);
        %
        %                         for j = 1:numel(diagramNames)
        %                             dName = diagramNames{j};
        %                             dNode = uitreenode(dtNode, 'Text', dName);
        %
        %                             % Store the full row as NodeData
        %                             rows = find(diagramRows & strcmp(app.DiagramsDefinitionTable.Name, dName), 1);
        %
        %                             if ~isempty(rows)
        %                                 dNode.NodeData = table2struct(app.DiagramsDefinitionTable(rows, :));
        %                             end
        %
        %                         end
        %                     end
        %                 end
        %             end
        %
        %             % Expand tree for user visibility
        %             expand(Tree2Update);
        %         end
        function update_diagram_tree(app,Tree2Update)

    if ~exist('Tree2Update','var')
        Tree2Update = app.DiagramTree;
    end

    ResetTree(app,Tree2Update);

    % --- Read dropdown selection ---
    selectedPath = app.DiagramTreeDropdown.Value;
    levels = strtrim(split(selectedPath,'>'));
    hierarchyLevels = levels(1:end-1);   % everything except 'Name'

    T = app.DiagramsDefinitionTable;

    % Initialize processing queue
    nodeQueue = {struct( ...
        'Parent', Tree2Update, ...
        'Table', T, ...
        'LevelIdx', 1)};

    while ~isempty(nodeQueue)

        task = nodeQueue{1};
        nodeQueue(1) = [];

        parentNode = task.Parent;
        subT = task.Table;
        levelIdx = task.LevelIdx;

        % -------------------------------
        % FINAL LEVEL → CREATE NAME NODES
        % -------------------------------
        if levelIdx > numel(hierarchyLevels)

            % Create ONE node per table row
            for r = 1:height(subT)

                dName = subT.Name{r};
                dNode = uitreenode(parentNode,'Text',dName);

                % ---- NodeData (FULL ROW, unchanged logic style) ----
                dNode.NodeData = table2struct(subT(r,:));
                % ----------------------------------------------------

            end

        else
            % -------------------------------
            % INTERMEDIATE LEVELS
            % -------------------------------
            currentField = hierarchyLevels{levelIdx};
            values = unique(subT.(currentField),'stable');

            for i = 1:numel(values)
                val = values{i};

                rows = strcmp(subT.(currentField),val);
                nextT = subT(rows,:);

                node = uitreenode(parentNode,'Text',val);
                node.NodeData = struct( ...
                    'Level', currentField, ...
                    'Name', val, ...
                    'Diagrams', [] ...
                );

                % Add next level to queue
                nodeQueue{end+1} = struct( ...
                    'Parent', node, ...
                    'Table', nextT, ...
                    'LevelIdx', levelIdx+1);
            end
        end
    end

    expand(Tree2Update);

end


        function [T, axisNames] = prepare_axes_for_plot(app,T, NodeData)

            try
                % Prepare X and Y (and potentially Z) columns for plotting.
                % Returns T with added columns, and axisNames containing the new column names.
                axesFields = {'X','Y'};  % extend to 'Z' if needed
                axisNames = struct();
                for i = 1:numel(axesFields)
                    ax = axesFields{i};
                    compField = [ax '_Components'];
                    typeField = [ax '_Type'];

                    if ~isfield(NodeData, compField) || ~isfield(NodeData, typeField)
                        continue
                    end

                    allowedFuncs = {'log','log10','exp','sqrt','abs','sin','cos','tan'};
                    comps = strtrim(strsplit(NodeData.(compField), [{';','/','(',')','*','-','+',':','^'},allowedFuncs]));
                    comps = comps(~cellfun(@isempty,comps));

                    if sum(ismember(comps,T.Properties.VariableNames))==0 || ~isequal(sum(ismember(comps,T.Properties.VariableNames)),numel(comps)) || sum(ismember(T.Properties.VariableNames,app.MassList) & ismember(replace(T.Properties.VariableNames,{'0','1','2','3','4','5','6','7','8','9','_mat_ugg'},''),comps))>=numel(comps)
                        for n=1:numel(comps)
                            if any(ismember(T.Properties.VariableNames,app.MassList) & ismember(replace(T.Properties.VariableNames,{'0','1','2','3','4','5','6','7','8','9','_mat_ugg'},''),comps{n}))
                                NewVarName=T.Properties.VariableNames(ismember(T.Properties.VariableNames,app.MassList) & ismember(replace(T.Properties.VariableNames,{'0','1','2','3','4','5','6','7','8','9','_mat_ugg'},''),comps{n}));
                                if numel(NewVarName)==1
                                    T(:,comps(n))=T(:,ismember(T.Properties.VariableNames,app.MassList) & ismember(replace(T.Properties.VariableNames,{'0','1','2','3','4','5','6','7','8','9','_mat_ugg'},''),comps{n}));
                                    comps(n)=NewVarName;
                                else
                                    keyboard
                                end
                            end
                        end


                    end

                    axType = NodeData.(typeField);
                    newColName = ax; % default

                    vec = nan(height(T),1); % default NaN

                    % --- Handle direct, ratio, sum, formula ---
                    if startsWith(lower(axType),'formula')
                        exprStr = NodeData.(compField);

                        % --- Extract potential variable names ---
                        tokens = regexp(exprStr,'[A-Za-z]\w*','match');
                        vars   = unique(tokens);

                        % --- Remove allowed math functions from variable list ---
                        vars = setdiff(vars, allowedFuncs);

                        % --- Check if all remaining variables exist in table ---
                        if all(ismember(vars, T.Properties.VariableNames))

                            % Force element-wise operations automatically
                            exprStr = strrep(exprStr,'^','.^');
                            exprStr = strrep(exprStr,'*','.*');
                            exprStr = strrep(exprStr,'/','./');

                            % Build function
                            argList = strjoin(vars, ',');
                            funcStr = ['@(' argList ') ' exprStr];
                            f = str2func(funcStr);

                            % Collect inputs
                            inputs = cell(1,numel(vars));
                            for k = 1:numel(vars)
                                inputs{k} = T.(vars{k});
                            end

                            try
                                vec = f(inputs{:});
                            catch
                                vec = nan(height(T),1);
                            end

                            newColName = ['formula_' regexprep(exprStr,'[^A-Za-z0-9]','_')];
                        end
                    else
                        switch lower(axType)
                            case 'direct'
                                varName = comps{1};
                                if ismember(varName, T.Properties.VariableNames)
                                    vec = T.(varName);
                                    newColName = varName;
                                end
                            case 'ratio'
                                if all(ismember(comps(1:2), T.Properties.VariableNames))

                                    v1 = T.(comps{1});
                                    v2 = T.(comps{2});
                                    vec = v1 ./ v2;

                                else
                                    vec = nan(height(T),1);
                                end
                                newColName = [comps{1} '_' comps{2}];
                            case 'sum'
                                vec = zeros(height(T),1);
                                for j = 1:numel(comps)
                                    if ismember(comps{j}, T.Properties.VariableNames)
                                        vec = vec + T.(comps{j});
                                    else
                                        vec = vec + nan(height(T),1);
                                    end
                                end
                                newColName = strjoin(comps,'_');
                        end
                    end

                    % Add new column
                    T.(newColName) = vec;
                    axisNames.(ax) = newColName;

                end
            catch ME
                DisplayError(app, ME,'Diagram Table Prep')
            end

        end

        function plotDiagram(app,selectedNode)
            [Tout, axisNames] = prepare_axes_for_plot(app,app.UITable.Data, selectedNode.NodeData);
            if isfield(axisNames,'X')&& isfield(axisNames,'Y')&&not(isempty(axisNames.X))&&not(isempty(axisNames.Y))
                [~,~,options] = plot_XY_data_fun([]);

                options.type.Value = 'custom_XY';
                options.InputTable=Tout;
                options.X1 = axisNames.X;
                options.Y1 = axisNames.Y;

                selectedNode.NodeData.Title_TeX

                options.custom.xlabel=selectedNode.NodeData.X_TeX;
                options.custom.ylabel=selectedNode.NodeData.Y_TeX;
                %                         app.UIAxes.Title.String=diagramInfo.Title_TeX;
                %         app.UIAxes.XAxis.Label.String=diagramInfo.X_TeX;
                %         app.UIAxes.YAxis.Label.String=diagramInfo.Y_TeX;
                %
                % XScale
                if isfield(selectedNode.NodeData,'XScale') && ...
                        any(ismember(string(selectedNode.NodeData.XScale),["linear","log"]))
                    options.custom.XScale = char(string(selectedNode.NodeData.XScale));
                else
                    options.custom.XScale = 'linear';
                end

                % YScale
                if isfield(selectedNode.NodeData,'YScale') && ...
                        any(ismember(string(selectedNode.NodeData.YScale),["linear","log"]))
                    options.custom.YScale = char(string(selectedNode.NodeData.YScale));
                else
                    options.custom.YScale = 'linear';
                end

                % XGrid
                if isfield(selectedNode.NodeData,'XGrid') && ...
                        any(ismember(string(selectedNode.NodeData.XGrid),["on","off","true","false"]))
                    options.custom.XGrid = char(string(selectedNode.NodeData.XGrid));
                else
                    options.custom.XGrid = 'off';
                end

                % YGrid
                if isfield(selectedNode.NodeData,'YGrid') && ...
                        any(ismember(string(selectedNode.NodeData.YGrid),["on","off","true","false"]))
                    options.custom.YGrid = char(string(selectedNode.NodeData.YGrid));
                else
                    options.custom.YGrid = 'off';
                end

                % XMinorGrid
                if isfield(selectedNode.NodeData,'XMinorGrid') && ...
                        any(ismember(string(selectedNode.NodeData.XMinorGrid),["on","off","true","false"]))
                    options.custom.XMinorGrid = char(string(selectedNode.NodeData.XMinorGrid));
                else
                    options.custom.XMinorGrid = 'off';
                end

                % YMinorGrid
                if isfield(selectedNode.NodeData,'YMinorGrid') && ...
                        any(ismember(string(selectedNode.NodeData.YMinorGrid),["on","off","true","false"]))
                    options.custom.YMinorGrid = char(string(selectedNode.NodeData.YMinorGrid));
                else
                    options.custom.YMinorGrid = 'off';
                end


                options.which='custom_XY';
                %  options.which=['custom_XY ' selectedNode.NodeData.Title_TeX];
                options.type.Value='custom_XY';
                options.NewPlot=true;
                options.SubDiv=app.UseDataSubdivisionCheckBox.Value;
                options.custom.Interpreter='tex';%app.LabelInterpreterDropDown_2.Value;

                %options.ax2plot=app.UIAxes;
                send_to_EasyPlot(app,options)

                %[ax2plot,~,~] = plot_XY_data_fun(Tout, app.UIAxes, options);
            end

        end
    end

    methods (Access = public)

        function update_plot_fun(app,~)
            VarSelectionTreeCheckedNodesChanged(app)
        end

        function update_public_fun(app)
            UpdateVariableTree(app)
            if ismember(app.ListBox.Value,app.UITable.Data.Properties.VariableNames)
                [x, ~]=  unique(app.UITable.Data.(char(app.ListBox.Value)),'stable');
                x(cellfun(@isempty, x))={' '};


                app.nEditField.Value=numel(x);

                if app.UseDataSubdivisionCheckBox.Value==false
                    app.ListBox_2.Visible=false;
                    app.ListBox_2.Items={};

                elseif numel(x)>50
                    app.ListBox_2.Visible=true;
                    app.ListBox_2.Items=x;
                    app.ListBox_2.Value=x(1:5);

                else
                    try
                        app.ListBox_2.Items=x;
                        app.ListBox_2.Value=x;
                    catch

                    end
                    app.ListBox_2.Visible=true;

                end
            else
                app.ListBox_2.Items={};
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, soureceapp, mainapp)
            CenterFig_fun(app.VisModule_GUI)

% mainapp ist original von dir, sourceapp von mir
% sourceapp ist ICP-Base wo es auch dann app.sourceapp.installpath gibt

            if not(exist("mainapp","var"))
                mainapp=table();
            end


            if istable(mainapp)
                input_table=mainapp;
                % app.UITable.Visible=false;
                %     app.VarSelectionTree.Layout.Row=[1 4];
                app.TabGroup2.Layout.Column(1)=1;
                app.Tree.Visible=false;
                app.Tree_2.Visible=false;
                app.DropDown.Visible=false;
                app.MinPlotX=struct();
                app.MinPlotX.Standalone=true;
                app.MinPlotX.MinPlotXFigures=struct();


                [T_new2, T_DL2] = FindDetection_Limits2(app, input_table);


                app.DetectionLimitsTable=T_DL2;
                input_table=T_new2;
                if not(any(strcmp('_mat_ugg',input_table.Properties.VariableNames)))
                    app.ScaleDropDown.Value='All Log';
                    ScaleDropDownValueChanged(app)
                end
                if not(any(strcmp('Active',input_table.Properties.VariableNames)))
                    T=table();
                    T.Active(1:size(input_table,1))=true;
                    input_table=[T input_table];
                end

                app.UITable.Data=input_table;
                app.UITable.ColumnName=input_table.Properties.VariableNames;
                app.TabGroup2.SelectedTab= app.TabGroup2.Children(2);
                app.UpdateButton.Visible=false;
                app.Hyperlink.Visible=true;
                app.MinPlotXLogo.Visible=true;

                %        elseif isstruct(mainapp)
                % mainapp.DataTable
                % mainapp.DetectionLimitsTable
            else
                app.Tree.Visible=true;
                app.Tree_2.Visible=true;
                app.DropDown.Visible=true;
                app.MinPlotX = mainapp;
            end

            ReadColorMaps(app)
            ReadTEData(app)

            try
                if isdeployed && not(isfield(app.MinPlotX,'Standalone'))
                    file= fullfile(app.MinPlotX.installpath,'Dev','TraceElements_UserNorm.txt');
                else
                    file=which('TraceElements_UserNorm.txt');
                end
                if not(isempty(file))
                    ReadTEData(app,file)
                end
            catch ME
                DisplayError(app, ME)
            end

            update_GUI(app)


            app.TabGroup.Visible=true;

        end

        % Button pushed function: HistogramFilterButton, 
        % HistogramFilterButton_2
        function HistHistogramFilterButtonPushed(app, event)

            fun=@(app,state)(update_plot_fun(app,state));
            HistogramFilterModule(app,app.UITable,fun)
        end

        % Callback function: DropDown, UpdateButton
        function UpdateButtonPushed(app, event)
            %ResetTree(app,app.Tree_2)
            VarSelectionTreeCheckedNodesChanged(app)

        end

        % Callback function: Tree, Tree_2
        function Tree_2CheckedNodesChanged(app, event)
            update_table(app);
        end

        % Menu selected function: CheckallMenu
        function CheckallMenuSelected(app, event)
            app.Tree_2.CheckedNodes= app.Tree_2.Children;
            update_table(app);

        end

        % Menu selected function: UncheckallMenu
        function UncheckallMenuSelected(app, event)
            app.Tree_2.CheckedNodes=[];
            update_table(app);
        end

        % Callback function: DisplayFilteredCheckBox, FigureFontSizeSpinner, 
        % FilterasNaNCheckBox, LayoutDropDown, LineColorDropDown, 
        % LineStyleDropDown, LineWidthSpinner, MarkerEdgeColorDropDown, 
        % MarkerFaceColorDropDown, MarkerSizeSpinner, 
        % NumberofColumnsSpinner, RowHightSpinner, SymbolDropDown, 
        % VarSelectionTree
        function VarSelectionTreeCheckedNodesChanged(app, event)
            checkedNodes = app.VarSelectionTree.CheckedNodes;

            app.Panel.Visible=false;
            if not (isempty(checkedNodes))
                symb=app.SymbolDropDown.Value;

                vars2plot={app.VarSelectionTree.CheckedNodes.Text};
                stacked=strcmp(app.LayoutDropDown.Value,'Stacked');

                if stacked
                    n_col=1;
                    n_row=1;
                    Scrollable_state='off';

                elseif strcmp(app.LayoutDropDown.Value,'Individual')
                    InputTable=app.UITable.Data;
                    T_data=InputTable.(char(app.ListBox.Value));
                    T_data(cellfun(@isempty, T_data))={' '};

                    u_t=unique(T_data(ismember(T_data,app.ListBox_2.Value)),'stable');

                    n_col=app.NumberofColumnsSpinner.Value;
                    n_row=(ceil(numel(u_t)/n_col));
                    Scrollable_state='off';

                else
                    n_col=app.NumberofColumnsSpinner.Value;
                    n_row=(ceil(numel(vars2plot)/n_col));
                    Scrollable_state='off';
                end
                app.GridLayout_Plots=uigridlayout(app.Panel,[n_row n_col]);

                if not(stacked)

                    %  rowhight=app.RowHightSpinner.Value;

                    %         A = rowhight*ones(1,n_row);
                    %         app.GridLayout_Plots.RowHeight="fit";%A;
                    app.GridLayout_Plots.RowHeight=repmat({'1x'},1,n_row);

                end
                %
                app.GridLayout_Plots.ColumnWidth=repmat({'1x'},1,n_col);
                set(app.GridLayout_Plots,'Scrollable', Scrollable_state);


                if not(ismember(app.XAxesVariableDropDown.Value,app.UITable.Data.Properties.VariableNames))
                    x=1:size(app.UITable.Data,1);
                else
                    x=app.UITable.Data.(char(app.XAxesVariableDropDown.Value));
                end


                if strcmp(app.LayoutDropDown.Value,'Individual')

                    for k=1:numel(u_t)
                        symb=app.SymbolDropDown.Value;

                        legend_str={};
                        ax2plot=uiaxes(app.GridLayout_Plots, 'HandleVisibility','on');

                        ax2plot.NextPlot='add';
                        ax2plot.FontSize=app.FigureFontSizeSpinner.Value;

                        pbaspect(ax2plot,'auto');
                        mfc=app.MarkerFaceColorDropDown.Value;

                        mec= app.MarkerEdgeColorDropDown.Value;
                        filered_val=false;
                        for n=1:numel(vars2plot)
                            y=app.UITable.Data.(char(vars2plot(n)));

                            if app.FilterasNaNCheckBox.Value
                                u_c_f=ismember(T_data,u_t(k)) ;

                                x_plot=x(u_c_f);
                                y_plot=y(u_c_f);

                                x_plot(~(app.UITable.Data.Active(u_c_f)))=nan;
                                y_plot(~(app.UITable.Data.Active(u_c_f)))=nan;
                            else
                                u_c_f=ismember(T_data,u_t(k)) &app.UITable.Data.Active;

                                x_plot=x(u_c_f);
                                y_plot=y(u_c_f);
                            end
                            h=plot(ax2plot,x_plot,y_plot,'Color',mec,'Marker',symb,'MarkerFaceColor',mfc,'MarkerEdgeColor',mec,'LineStyle',app.LineStyleDropDown.Value,'LineWidth',app.LineWidthSpinner.Value,'MarkerSize',app.MarkerSizeSpinner.Value);

                            if app.DisplayFilteredCheckBox.Value && not(isempty(x(~app.UITable.Data.Active & ismember(T_data,u_t(k)))))
                                h=plot(ax2plot,x(ismember(T_data,u_t(k)) & ~app.UITable.Data.Active),y(ismember(T_data,u_t(k)) & ~app.UITable.Data.Active),'Color','r','Marker','x','MarkerFaceColor','r','MarkerEdgeColor','r','LineStyle','none','LineWidth',app.LineWidthSpinner.Value,'MarkerSize',app.MarkerSizeSpinner.Value);
                                filered_val=true;
                            end

                            symb=IterateMarkerSymbol(app,symb);
                            mfc=IterateMarkerColor(app,mfc);
                            mec=IterateMarkerColor(app,mec);
                        end
                        title(ax2plot,u_t{k},'Rotation',0,'FontWeight','bold','Color','k','Interpreter','none');

                        if app.DisplayFilteredCheckBox.Value && not(isempty(x(~app.UITable.Data.Active))) && filered_val==true
                            legend_str=vars2plot;
                            legend_str(end+1)={'Filtered'};
                            h=get(ax2plot,'Children'); % grab all the axes handles at once
                            legend(flipud(h([1,2:2:end])),legend_str,'interpreter','none')

                        else
                            legend(ax2plot,vars2plot,'interpreter','none')
                        end

                        %                         if app.CustomAxesLabelsCheckBox.Value
                        %                             xlabel(ax2plot,app.CustomXLabelEditField.Value,'interpreter',app.LabelInterpreterDropDown.Value)
                        %                             ylabel(ax2plot,app.CustomYLabelEditField.Value,'interpreter',app.LabelInterpreterDropDown.Value)
                        %                         else
                        %                             xlabel(ax2plot,app.XAxesVariableDropDown.Value,'interpreter',app.LabelInterpreterDropDown.Value)
                        %                         end
                        if ax2plot.Layout.Row==numel(app.GridLayout_Plots.RowHeight)
                            if app.CustomAxesLabelsCheckBox.Value
                                xlabel(ax2plot,app.CustomXLabelEditField.Value,'interpreter',app.LabelInterpreterDropDown.Value)
                                ylabel(ax2plot,app.CustomYLabelEditField.Value,'interpreter',app.LabelInterpreterDropDown.Value)
                            else
                                xlabel(ax2plot,app.XAxesVariableDropDown.Value,'interpreter',app.LabelInterpreterDropDown.Value)

                            end
                        end
                        try
                            if not(isempty(x(u_c_f))) && min(x(u_c_f))< max(x(u_c_f))
                                ax2plot.XLim=[min(x(u_c_f)) max(x(u_c_f))];
                            end
                        catch ME
                            DisplayError(app, ME,'XLim Update Error')

                        end
                    end
                else

                    mfc=app.MarkerFaceColorDropDown.Value;

                    mec= app.MarkerEdgeColorDropDown.Value;
                    filered_val=false;

                    for n=1:numel(vars2plot)
                        if stacked && n==1
                            ax2plot=uiaxes(app.GridLayout_Plots, 'HandleVisibility','on');
                            ax2plot.NextPlot='add';
                            ax2plot.FontSize=app.FigureFontSizeSpinner.Value;
                        elseif stacked==false
                            ax2plot = uiaxes(app.GridLayout_Plots, 'HandleVisibility','on');
                            pbaspect(ax2plot,'auto');
                            ax2plot.NextPlot='add';
                            ax2plot.FontSize=app.FigureFontSizeSpinner.Value;

                            title(ax2plot,vars2plot(n),'Rotation',0,'FontWeight','bold','Color','k','Interpreter','none');

                        end
                        y=app.UITable.Data.(char(vars2plot(n)));

                        if app.FilterasNaNCheckBox.Value
                            x_plot=x;
                            y_plot=y;
                            x_plot(~app.UITable.Data.Active)=nan;
                            y_plot(~app.UITable.Data.Active)=nan;
                        else
                            x_plot=x(app.UITable.Data.Active);
                            y_plot=y(app.UITable.Data.Active);
                        end

                        h=plot(ax2plot,x_plot,y_plot,'Color',mec,'Marker',symb,'MarkerFaceColor',mfc,'MarkerEdgeColor',mec,'LineStyle',app.LineStyleDropDown.Value,'LineWidth',app.LineWidthSpinner.Value,'MarkerSize',app.MarkerSizeSpinner.Value);

                        symb=IterateMarkerSymbol(app,symb);
                        mfc=IterateMarkerColor(app,mfc);
                        mec=IterateMarkerColor(app,mec);

                        if app.DisplayFilteredCheckBox.Value && not(isempty(x(~app.UITable.Data.Active)))
                            h=plot(ax2plot,x(~app.UITable.Data.Active),y(~app.UITable.Data.Active),'Color','r','Marker','x','MarkerFaceColor','r','MarkerEdgeColor','r','LineStyle','none','LineWidth',app.LineWidthSpinner.Value,'MarkerSize',app.MarkerSizeSpinner.Value);
                            filered_val=true;
                        end

                        if ax2plot.Layout.Row==numel(app.GridLayout_Plots.RowHeight)
                            if app.CustomAxesLabelsCheckBox.Value
                                xlabel(ax2plot,app.CustomXLabelEditField.Value,'interpreter',app.LabelInterpreterDropDown.Value)
                                ylabel(ax2plot,app.CustomYLabelEditField.Value,'interpreter',app.LabelInterpreterDropDown.Value)
                            else
                                xlabel(ax2plot,app.XAxesVariableDropDown.Value,'interpreter',app.LabelInterpreterDropDown.Value)

                            end
                        end

                    end

                    try
                        if not(isempty(x(app.UITable.Data.Active))) && min(x(app.UITable.Data.Active))< max(x(app.UITable.Data.Active))
                            ax2plot.XLim=[min(x(app.UITable.Data.Active)) max(x(app.UITable.Data.Active))];
                        end
                    catch ME
                        DisplayError(app, ME,'XLim Update Error')
                    end

                end
                if stacked
                    if app.DisplayFilteredCheckBox.Value && not(isempty(x(~app.UITable.Data.Active))) &&filered_val==true;
                        legend_str=vars2plot;
                        legend_str(end+1)={'Filtered'};
                        h=get(ax2plot,'Children'); % grab all the axes handles at once
                        legend(flipud(h([1,2:2:end])),legend_str,'interpreter','none')
                    else
                        legend(ax2plot,vars2plot,'interpreter','none')
                    end
                end

                drawnow
                app.Panel.Visible=true;

            end


        end

        % Button pushed function: NewEasyPlotButton
        function NewEasyPlotButtonPushed(app, event)

            checkedNodes = app.VarSelectionTree.CheckedNodes;
            if not (isempty(checkedNodes))
                settings.which='Profile_Plot';
                settings.NewPlot=true;
                settings.SubDiv=app.UseDataSubdivisionCheckBox.Value;

                send_to_EasyPlot(app,settings)
            end
        end

        % Button pushed function: AddToEasyPlotButton
        function AddToEasyPlotButtonPushed(app, event)
            if isfield(app.MinPlotX.MinPlotXFigures,'hFig_type') && not(isempty(app.MinPlotX.MinPlotXFigures.hFig_type)) && any(startsWith(app.MinPlotX.MinPlotXFigures.hFig_type,'Profile_Plot'))
                list= app.MinPlotX.MinPlotXFigures.hFig_type((startsWith(app.MinPlotX.MinPlotXFigures.hFig_type,'Profile_Plot')));
                if numel(list)==1
                    which=list{1};
                else
                    [indx,~] = listdlg('ListString',list,'SelectionMode','single');
                    if isempty(indx)
                        return
                    end

                    which=list{indx};
                end
                figure(app.VisModule_GUI)
                settings.Add2Plot=true;
                settings.which=which;
            else
                settings.NewPlot=true;
            end

            settings.SubDiv=app.UseDataSubdivisionCheckBox.Value;

            send_to_EasyPlot(app,settings)


        end

        % Button pushed function: IndividualEasyPlotButton
        function IndividualEasyPlotButtonPushed(app, event)
            checkedNodes = app.VarSelectionTree.CheckedNodes;

            if not(isempty(checkedNodes))

                if app.nEditField.Value>12
                    answer = uiconfirm(app.VisModule_GUI, ...
                        ['Are you sure you want to generate plots for ' num2str((app.nEditField.Value)) ' Entries?'],"Generate Plots", ...
                        "Icon","warning");

                    if strcmp(answer,'Cancel')
                        return
                    end
                end
                settings.NewPlot=true;
                settings.SubDiv=false;
                InputTable=app.UITable.Data;
                T_data=InputTable.(char(app.ListBox.Value));
                T_data(cellfun(@isempty, T_data))={' '};
                settings.which='Profile_Plot';


                u_t=unique(T_data(ismember(T_data,app.ListBox_2.Value)),'stable');

                for k=1:numel(unique(u_t))

                    u_c_f=ismember(T_data,u_t(k)) ;
                    settings.InputTable=InputTable(u_c_f,:);
                    settings.title=u_t(k);
                    send_to_EasyPlot(app,settings)

                end

            end
        end

        % Value changed function: UseDataSubdivisionCheckBox
        function UseDataSubdivisionCheckBoxValueChanged(app, event)
            app.ListBox.Visible=app.UseDataSubdivisionCheckBox.Value;
            app.nEditField.Visible=app.UseDataSubdivisionCheckBox.Value;
            app.nEditFieldLabel.Visible=app.UseDataSubdivisionCheckBox.Value;
            %  app.XAxesVariableDropDown.Visible=app.UseDataSubdivisionCheckBox.Value;
            %    app.XAxesVariableDropDownLabel.Visible=app.UseDataSubdivisionCheckBox.Value;
            ListBoxValueChanged(app)
            app.IndividualEasyPlotButton.Visible=app.UseDataSubdivisionCheckBox.Value;
            app.IndividualXYEasyPlotButton.Visible=app.UseDataSubdivisionCheckBox.Value;

            if app.UseDataSubdivisionCheckBox.Value==true && not(isempty(app.ListBox.Value)) %%
                app.LayoutDropDown.Items={'Stacked','Tiled','Individual'};
            else
                app.LayoutDropDown.Items={'Stacked','Tiled'};
            end

            if any(endsWith(app.UITable.Data.Properties.VariableNames,'_incl_ugg')) &&any(endsWith(app.UITable.Data.Properties.VariableNames,'_mat_ugg')) && app.UseDataSubdivisionCheckBox.Value
                app.SplitMatrixInclusionButton.Visible=true;
            elseif ~isempty(app.TableRaw)
                app.ResetButton.Visible=true;
            else
                app.SplitMatrixInclusionButton.Visible=false;
                app.ResetButton.Visible=false;
            end


        end

        % Value changed function: ListBox
        function ListBoxValueChanged(app, event)
            if not(isempty(app.TableRaw))
                ResetButtonPushed(app)
            end


            if ismember(app.ListBox.Value,app.UITable.Data.Properties.VariableNames)
                [x, ~]=  unique(app.UITable.Data.(char(app.ListBox.Value)),'stable');
                x(cellfun(@isempty, x))={' '};


                app.nEditField.Value=numel(x);

                if app.UseDataSubdivisionCheckBox.Value==false
                    app.ListBox_2.Visible=false;
                    app.ListBox_2.Items={};

                elseif numel(x)>50
                    app.ListBox_2.Visible=true;
                    app.ListBox_2.Items=x;
                    app.ListBox_2.Value=x(1:5);

                else
                    try
                        app.ListBox_2.Items=x;
                        app.ListBox_2.Value=x;
                    catch

                    end
                    app.ListBox_2.Visible=true;

                end
            else
                app.ListBox_2.Items={};
            end
            if any(endsWith(app.UITable.Data.Properties.VariableNames,'_incl_ugg')) &&any(endsWith(app.UITable.Data.Properties.VariableNames,'_mat_ugg'))
                app.SplitMatrixInclusionButton.Visible=true;
            elseif ~isempty(app.TableRaw)
                app.ResetButton.Visible=true;

            end

        end

        % Value changed function: ListBox_2
        function ListBox_2ValueChanged(app, event)
            value = app.ListBox_2.Value;
            if not(isempty(value))
                app.nEditField.Value=numel(value);
            end
        end

        % Button pushed function: ExportButton_2
        function ExportButton_2Pushed(app, event)
            filePath = app.MinPlotX.WorkingDirectoryEditField.Value;
            fileName ='graphic.pdf';
            % or query for filename:
            [fileName, filePath] = uiputfile(['*.pdf'],'Export',fullfile(filePath,fileName));
            exportgraphics(app.GridLayout_Plots, fullfile(filePath,fileName),'ContentType','vector')
            %    web(fullfile(filePath,fileName))
        end

        % Drop down opening function: XAxesVariableDropDown
        function XAxesVariableDropDownOpening(app, event)
            app.XAxesVariableDropDown.Items=[{'1:n'} app.UITable.ColumnName((any(cellfun(@isnumeric,table2cell(app.UITable.Data)))|any(cellfun(@islogical,table2cell(app.UITable.Data)))))'];
        end

        % Value changed function: CustomAxesLabelsCheckBox
        function CustomAxesLabelsCheckBoxValueChanged(app, event)
            value=app.CustomAxesLabelsCheckBox.Value;
            app.CustomXLabelEditFieldLabel.Visible=value;
            app.CustomXLabelEditField.Visible=value;
            app.CustomYLabelEditFieldLabel.Visible=value;
            app.CustomYLabelEditField.Visible=value;

            % app.LabelInterpreterDropDown.Visible=value;
            %  app.LabelInterpreterDropDownLabel.Visible=value;

            app.CustomXLabelEditField.Value=app.XAxesVariableDropDown.Value;

        end

        % Button pushed function: SpotVisModuleButton
        function SpotVisModuleButtonPushed(app, event)
            app.UITable.Data.Fileindex=app.UITable.Data.SpotId;
            SpotVisMinPlotX(app, app.UITable)
        end

        % Button pushed function: NewXYEasyPlotButton_4
        function NewXYEasyPlotButton_4Pushed(app, event)

            if strcmp(app.PlotType_DropDown.Value,'XYZ')
                settings.which='custom_XYZ';
                settings.type.Value='custom_XYZ';
            elseif strcmp(app.PlotType_DropDown.Value,'XY')
                settings.which='custom_XY';
                settings.type.Value='custom_XY';
            elseif strcmp(app.PlotType_DropDown.Value,'Ternary')
                settings.which='custom_tern';
                settings.type.Value='custom_tern';
            elseif strcmp(app.PlotType_DropDown.Value,'Tetrahedron')
                settings.which='custom_tetra';
                settings.type.Value='custom_tetra';
            elseif strcmp(app.PlotType_DropDown.Value,'XY Ratio A/B vs C/D')
                settings.which='custom_ratio_XY';
                settings.type.Value='custom_XY';
            elseif strcmp(app.PlotType_DropDown.Value,'XYZ Ratio A/B vs C/D vs E/F')
                settings.which='custom_ratio_XYZ';
                settings.type.Value='custom_XYZ';
            end


            settings.NewPlot=true;
            settings.SubDiv=app.UseDataSubdivisionCheckBox.Value;

            settings.custom.Interpreter=app.LabelInterpreterDropDown_2.Value;

            if strcmp(app.XAxesVariableDropDown_2.Value,'1:n')
                settings.X1=[];
                settings.custom.xlabel="1:n";
            elseif strcmp(app.XAxesVariableDropDown_2.Value,'1')
                settings.X1=[];
            else
                settings.X1=app.XAxesVariableDropDown_2.Value;
                settings.custom.xlabel=app.XLabelEditField.Value;
            end

            if strcmp(app.YAxesVariableDropDown.Value,'1:n')
                settings.Y1=[];
                settings.custom.ylabel="1:n";
            elseif strcmp(app.YAxesVariableDropDown.Value,'1')
                settings.Y1=[];
            else
                settings.Y1=app.YAxesVariableDropDown.Value;
                settings.custom.ylabel=app.YLabelEditField.Value;
            end

            if strcmp(app.PlotType_DropDown.Value,'XYZ') || strcmp(app.PlotType_DropDown.Value,'Ternary') || strcmp(app.PlotType_DropDown.Value,'Tetrahedron') || strcmp(app.PlotType_DropDown.Value,'XY Ratio A/B vs C/D')|| strcmp(app.PlotType_DropDown.Value,'XYZ Ratio A/B vs C/D vs E/F')
                if strcmp(app.ZAxesVariableDropDown.Value,'1:n')
                    settings.custom.zlabel="1:n";
                    settings.Z1=[];
                elseif strcmp(app.ZAxesVariableDropDown.Value,'1')
                    settings.Z1=[];
                else
                    settings.Z1=app.ZAxesVariableDropDown.Value;
                    settings.custom.zlabel=app.ZLabelEditField.Value;
                end
            end

            if  strcmp(app.PlotType_DropDown.Value,'Tetrahedron')   || strcmp(app.PlotType_DropDown.Value,'XY Ratio A/B vs C/D')|| strcmp(app.PlotType_DropDown.Value,'XYZ Ratio A/B vs C/D vs E/F')
                if strcmp(app.DAxesVariableDropDown.Value,'1:n')
                    settings.custom.dlabel="1:n";
                    settings.D1=[];
                elseif strcmp(app.DAxesVariableDropDown.Value,'1')
                    settings.D1=[];
                else
                    settings.D1=app.DAxesVariableDropDown.Value;
                    settings.custom.dlabel=app.DLabelEditField.Value;
                end
            end

            if strcmp(app.PlotType_DropDown.Value,'XY Ratio A/B vs C/D')
                settings.custom.xlabel=[app.XAxesVariableDropDown_2.Value '/' app.YAxesVariableDropDown.Value ];
                settings.custom.ylabel=[app.ZAxesVariableDropDown.Value '/' app.DAxesVariableDropDown.Value ];
            end

            if strcmp(app.PlotType_DropDown.Value,'XYZ Ratio A/B vs C/D vs E/F')
                if strcmp(app.EAxesVariableDropDown.Value,'1')
                    settings.E1=[];
                else
                    settings.E1=app.EAxesVariableDropDown.Value;
                end
                if strcmp(app.FAxesVariableDropDown.Value,'1')
                    settings.F1=[];
                else
                    settings.F1=app.FAxesVariableDropDown.Value;
                end
                settings.custom.xlabel=[app.XAxesVariableDropDown_2.Value '/' app.YAxesVariableDropDown.Value ];
                settings.custom.ylabel=[app.ZAxesVariableDropDown.Value '/' app.DAxesVariableDropDown.Value ];
                settings.custom.zlabel=[app.EAxesVariableDropDown.Value '/' app.FAxesVariableDropDown.Value ];

            end

            if app.CustomColormapCheckBox.Value
                if strcmp(app.CAxesVariableDropDown.Value,'1:n')
                    settings.C1=[];
                    settings.ColorData.cbar_label="1:n";
                else
                    settings.C1=app.CAxesVariableDropDown.Value;
                    settings.ColorData.cbar_label=app.ColorbarLabelEditField.Value;
                end
                settings.ColorData.Value=app.CustomColormapCheckBox.Value;
                settings.ColorData.colormap=PlotMap_UpdateColormap(app,app.Options_ColormapResEditField.Value);
            end

            if app.XScaleDropDown.Visible==true
                settings.custom.XScale=app.XScaleDropDown.Value;
                settings.custom.YScale=app.YScaleDropDown.Value;
            end

            if app.ZScaleDropDown.Visible==true
                settings.custom.ZScale=app.ZScaleDropDown.Value;
            end

            if app.CScaleDropDown.Visible==true
                settings.custom.CScale=app.CScaleDropDown.Value;
            end
            if strcmp(app.PlotType_DropDown.Value,'XY')
                settings.FigureName= ['XY: ' settings.X1 ' / ' settings.Y1];
            elseif strcmp(app.PlotType_DropDown.Value,'XYZ')
                settings.FigureName= ['XYZ: ' settings.X1 ' / ' settings.Y1 ' / ' settings.Z1];
            elseif strcmp(app.PlotType_DropDown.Value,'Ternary')
                settings.FigureName= ['Ternary: ' settings.X1 ' - ' settings.Y1 ' - ' settings.Z1];
            elseif   strcmp(app.PlotType_DropDown.Value,'Tetrahedron')
                settings.FigureName= ['Tetrahedron: ' settings.X1 ' - ' settings.Y1 ' - ' settings.Z1 ' - ' settings.D1];
            elseif strcmp(app.PlotType_DropDown.Value,'XY Ratio A/B vs C/D')
                settings.FigureName= ['XY Ratio A/B vs C/D: ' settings.custom.xlabel ' / ' settings.custom.ylabel];
            end
            send_to_EasyPlot(app,settings)

        end

        % Button pushed function: IndividualXYEasyPlotButton
        function IndividualXYEasyPlotButtonPushed(app, event)


            if app.nEditField.Value>12
                answer = uiconfirm(app.VisModule_GUI, ...
                    ['Are you sure you want to generate plots for ' num2str((app.nEditField.Value)) ' Entries?'],"Generate Plots", ...
                    "Icon","warning");

                if strcmp(answer,'Cancel')
                    return
                end
            end


            if strcmp(app.PlotType_DropDown.Value,'XYZ')
                settings.which='custom_XYZ';
                settings.type.Value='custom_XYZ';
            elseif strcmp(app.PlotType_DropDown.Value,'XY')
                settings.which='custom_XY';
                settings.type.Value='custom_XY';
            elseif strcmp(app.PlotType_DropDown.Value,'Ternary')
                settings.which='custom_tern';
                settings.type.Value='custom_tern';
            elseif strcmp(app.PlotType_DropDown.Value,'Tetrahedron')
                settings.which='custom_tetra';
                settings.type.Value='custom_tetra';
            end


            settings.NewPlot=true;
            settings.SubDiv=false;
            InputTable=app.UITable.Data;

            if isempty(app.ListBox.Value) || isempty(app.ListBox_2.Value)
                message={'No Subdivision Selected!'};
                uialert(app.VisModule_GUI,message,'Error');
                return
            end

            T_data=InputTable.(char(app.ListBox.Value));
            T_data(cellfun(@isempty, T_data))={' '};

            u_t=unique(T_data(ismember(T_data,app.ListBox_2.Value)),'stable');


            if strcmp(app.XAxesVariableDropDown_2.Value,'1:n')
                settings.X1=[];
                settings.custom.xlabel="1:n";
            else
                settings.X1=app.XAxesVariableDropDown_2.Value;
                settings.custom.xlabel=app.XLabelEditField.Value;
            end

            if strcmp(app.YAxesVariableDropDown.Value,'1:n')
                settings.Y1=[];
                settings.custom.ylabel="1:n";
            else
                settings.Y1=app.YAxesVariableDropDown.Value;
                settings.custom.ylabel=app.YLabelEditField.Value;
            end

            if strcmp(app.PlotType_DropDown.Value,'XYZ') || strcmp(app.PlotType_DropDown.Value,'Ternary') || strcmp(app.PlotType_DropDown.Value,'Tetrahedron')
                if strcmp(app.ZAxesVariableDropDown.Value,'1:n')
                    settings.custom.zlabel="1:n";
                    settings.Z1=[];
                else
                    settings.Z1=app.ZAxesVariableDropDown.Value;
                    settings.custom.zlabel=app.ZLabelEditField.Value;
                end
            end

            if  strcmp(app.PlotType_DropDown.Value,'Tetrahedron')
                if strcmp(app.DAxesVariableDropDown.Value,'1:n')
                    settings.custom.dlabel="1:n";
                    settings.D1=[];
                else
                    settings.D1=app.DAxesVariableDropDown.Value;
                    settings.custom.dlabel=app.DLabelEditField.Value;
                end
            end


            if app.CustomColormapCheckBox.Value==true
                if strcmp(app.CAxesVariableDropDown.Value,'1:n')
                    settings.C1=[];
                    settings.ColorData.cbar_label="1:n";
                else
                    settings.C1=app.CAxesVariableDropDown.Value;
                    settings.ColorData.cbar_label=app.ColorbarLabelEditField.Value;
                end
                settings.ColorData.Value=app.CustomColormapCheckBox.Value;
                settings.ColorData.colormap=PlotMap_UpdateColormap(app,app.Options_ColormapResEditField.Value);
            end

            symb_ini=app.SymbolDropDown.Value;
            mfc_ini=app.MarkerFaceColorDropDown.Value;
            symb=app.SymbolDropDown.Value;
            mfc=app.MarkerFaceColorDropDown.Value;


            for k=1:numel(unique(u_t))
                u_c_f=ismember(T_data,u_t(k)) ;
                settings.InputTable=InputTable(u_c_f,:);
                settings.title=u_t(k);
                send_to_EasyPlot(app,settings)
                symb=IterateMarkerSymbol(app,symb);
                app.SymbolDropDown.Value=symb;
                mfc=IterateMarkerColor(app,mfc);
                app.MarkerFaceColorDropDown.Value=mfc;

            end

            app.SymbolDropDown.Value=symb_ini;
            app.MarkerFaceColorDropDown.Value=mfc_ini;

        end

        % Value changed function: PlotType_DropDown
        function PlotType_DropDownValueChanged(app, event)


            if strcmp(app.PlotType_DropDown.Value,'Tetrahedron') || strcmp(app.PlotType_DropDown.Value,'XY Ratio A/B vs C/D') || strcmp(app.PlotType_DropDown.Value,'XYZ Ratio A/B vs C/D vs E/F')
                vis_state=true;

                app.XAxesVariableDropDown_2Label.Text='A Axes Variable';
                app.YAxesVariableDropDownLabel.Text='B Axes Variable';
                app.ZAxesVariableDropDownLabel.Text='C Axes Variable';
                app.CAxesVariableDropDownLabel.Text='Colormap Variable';

                app.DAxesVariableDropDown.Visible=true;
                app.DAxesVariableDropDownLabel.Visible=true;


                if strcmp(app.PlotType_DropDown.Value,'Tetrahedron')
                    app.DLabelEditField.Visible=true;
                    app.DLabelEditFieldLabel.Visible=true;

                                      app.ELabelEditField.Visible=false;
                    app.ELabelEditFieldLabel.Visible=false;
                    
                                app.FLabelEditField.Visible=false;
                    app.FLabelEditFieldLabel.Visible=false;
                    

                    app.XScaleDropDown.Value='linear';
                    app.XScaleDropDown.Visible=false;
                    app.XScaleDropDownLabel.Visible=false;

                    app.YScaleDropDown.Value='linear';
                    app.YScaleDropDown.Visible=false;
                    app.YScaleDropDownLabel.Visible=false;

                    app.ZScaleDropDown.Value='linear';
                    app.ZScaleDropDown.Visible=false;
                    app.ZScaleDropDownLabel.Visible=false;

                    app.NewXYEasyPlotButton_4.Text='New Tetrahedron EasyPlot';
                    app.IndividualXYEasyPlotButton.Text='Individual Tetrahedron EasyPlot';
                else
                    app.DLabelEditField.Visible=false;
                    app.DLabelEditFieldLabel.Visible=false;
                       app.ELabelEditField.Visible=false;
                    app.ELabelEditFieldLabel.Visible=false;
                                              app.FLabelEditField.Visible=false;
                    app.FLabelEditFieldLabel.Visible=false;

                    app.NewXYEasyPlotButton_4.Text='New Ratio EasyPlot';
                    app.IndividualXYEasyPlotButton.Text='Individual Ratio EasyPlot';

                    app.XAxesVariableDropDown_2.Items=[{'1'},app.XAxesVariableDropDown_2.Items];
                    app.YAxesVariableDropDown.Items=[{'1'},app.YAxesVariableDropDown.Items];
                    app.ZAxesVariableDropDown.Items=[{'1'},app.ZAxesVariableDropDown.Items];
                    app.DAxesVariableDropDown.Items=[{'1'},app.DAxesVariableDropDown.Items];

                    app.XAxesVariableDropDown_2.Items = app.XAxesVariableDropDown_2.Items(~cellfun(@(x) isequal(x, '1:n'), app.XAxesVariableDropDown_2.Items));
                    app.YAxesVariableDropDown.Items = app.YAxesVariableDropDown.Items(~cellfun(@(x) isequal(x, '1:n'), app.YAxesVariableDropDown.Items));
                    app.ZAxesVariableDropDown.Items = app.ZAxesVariableDropDown.Items(~cellfun(@(x) isequal(x, '1:n'), app.ZAxesVariableDropDown.Items));
                    app.DAxesVariableDropDown.Items = app.DAxesVariableDropDown.Items(~cellfun(@(x) isequal(x, '1:n'), app.DAxesVariableDropDown.Items));

                    app.XScaleDropDown.Value='linear';
                    app.XScaleDropDown.Visible=true;
                    app.XScaleDropDownLabel.Visible=true;

                    app.YScaleDropDown.Value='linear';
                    app.YScaleDropDown.Visible=true;
                    app.YScaleDropDownLabel.Visible=true;

                    app.ZScaleDropDown.Value='linear';
                    app.ZScaleDropDown.Visible=false;
                    app.ZScaleDropDownLabel.Visible=false;


                end


            else
                app.XAxesVariableDropDown_2Label.Text='X Axes Variable';
                app.YAxesVariableDropDownLabel.Text='Y Axes Variable';
                app.ZAxesVariableDropDownLabel.Text='Z Axes Variable';
                app.CAxesVariableDropDownLabel.Text='C Axes Variable';


                app.DAxesVariableDropDown.Visible=false;
                app.DAxesVariableDropDownLabel.Visible=false;

                app.DLabelEditField.Visible=false;
                app.DLabelEditFieldLabel.Visible=false;

                app.ELabelEditField.Visible=false;
                app.ELabelEditFieldLabel.Visible=false;

                app.FLabelEditField.Visible=false;
                app.FLabelEditFieldLabel.Visible=false;

                if strcmp(app.PlotType_DropDown.Value,'XYZ')
                    vis_state=true;
                    app.NewXYEasyPlotButton_4.Text='New XYZ EasyPlot';
                    app.IndividualXYEasyPlotButton.Text='Individual XYZ EasyPlot';

                    app.XScaleDropDown.Visible=true;
                    app.XScaleDropDownLabel.Visible=true;


                    app.YScaleDropDown.Visible=true;
                    app.YScaleDropDownLabel.Visible=true;


                    app.ZScaleDropDown.Visible=true;
                    app.ZScaleDropDownLabel.Visible=true;

                elseif strcmp(app.PlotType_DropDown.Value,'XY')
                    vis_state=true;
                    app.NewXYEasyPlotButton_4.Text='New XY EasyPlot';
                    app.IndividualXYEasyPlotButton.Text='Individual XY EasyPlot';

                    app.XScaleDropDown.Visible=true;
                    app.XScaleDropDownLabel.Visible=true;

                    app.YScaleDropDown.Visible=true;
                    app.YScaleDropDownLabel.Visible=true;

                    app.ZScaleDropDown.Visible=false;
                    app.ZScaleDropDownLabel.Visible=false;
                elseif strcmp(app.PlotType_DropDown.Value,'Ternary')
                    vis_state=true;
                    app.NewXYEasyPlotButton_4.Text='New Ternary EasyPlot';
                    app.IndividualXYEasyPlotButton.Text='Individual Ternary EasyPlot';

                    app.XScaleDropDown.Value='linear';
                    app.XScaleDropDown.Visible=false;
                    app.XScaleDropDownLabel.Visible=false;

                    app.YScaleDropDown.Value='linear';
                    app.YScaleDropDown.Visible=false;
                    app.YScaleDropDownLabel.Visible=false;

                    app.ZScaleDropDown.Value='linear';
                    app.ZScaleDropDown.Visible=false;
                    app.ZScaleDropDownLabel.Visible=false;

                end

                app.XAxesVariableDropDown_2.Items = app.XAxesVariableDropDown_2.Items(~cellfun(@(x) isequal(x, '1'), app.XAxesVariableDropDown_2.Items));
                app.YAxesVariableDropDown.Items = app.YAxesVariableDropDown.Items(~cellfun(@(x) isequal(x, '1'), app.YAxesVariableDropDown.Items));
                app.ZAxesVariableDropDown.Items = app.ZAxesVariableDropDown.Items(~cellfun(@(x) isequal(x, '1'), app.ZAxesVariableDropDown.Items));
                app.DAxesVariableDropDown.Items = app.DAxesVariableDropDown.Items(~cellfun(@(x) isequal(x, '1'), app.DAxesVariableDropDown.Items));

            end

            app.ColorbarLabelEditField.Visible=vis_state;

            app.ColorbarLabelEditFieldLabel.Visible=vis_state;
            if strcmp(app.PlotType_DropDown.Value,'XY')   
                app.ZLabelEditFieldLabel.Visible=false;
                app.ZLabelEditField.Visible=false;
                app.ZAxesVariableDropDown.Visible=false;
                app.ZAxesVariableDropDownLabel.Visible=false;
            elseif strcmp(app.PlotType_DropDown.Value,'XY Ratio A/B vs C/D')
                app.ZLabelEditFieldLabel.Visible=false;
                app.ZLabelEditField.Visible=false;
                app.ZAxesVariableDropDown.Visible=true;
                app.ZAxesVariableDropDownLabel.Visible=true;

                app.NewXYEasyPlotButton_4.Text='New XY Ratio EasyPlot';
                app.IndividualXYEasyPlotButton.Text='Individual XY Ratio EasyPlot';
            else
                app.ZLabelEditFieldLabel.Visible=vis_state;
                app.ZLabelEditField.Visible=vis_state;
                app.ZAxesVariableDropDown.Visible=vis_state;
                app.ZAxesVariableDropDownLabel.Visible=vis_state;
            end

            if  strcmp(app.PlotType_DropDown.Value,'XYZ Ratio A/B vs C/D vs E/F')
                app.ELabelEditFieldLabel.Visible=false;
                app.ELabelEditField.Visible=false;
                app.FLabelEditFieldLabel.Visible=false;
                app.FLabelEditField.Visible=false;
                app.ZAxesVariableDropDown.Visible=true;
                app.ZAxesVariableDropDownLabel.Visible=true;

                app.EAxesVariableDropDown.Visible=true;
                app.FAxesVariableDropDown.Visible=true;
                app.EAxesVariableDropDownLabel.Visible=true;
                app.FAxesVariableDropDownLabel.Visible=true;

                 app.ZScaleDropDown.Visible=true;
                    app.ZScaleDropDownLabel.Visible=true;

                app.NewXYEasyPlotButton_4.Text='New XYZ Ratio EasyPlot';
                app.IndividualXYEasyPlotButton.Text='Individual XYZ Ratio EasyPlot';

            else
                    app.EAxesVariableDropDown.Visible=false;
                app.FAxesVariableDropDown.Visible=false;
                app.EAxesVariableDropDownLabel.Visible=false;
                app.FAxesVariableDropDownLabel.Visible=false;
            end

            if  app.CustomColormapCheckBox.Value==true
                app.Panel_2.Visible=true;
                app.CAxesVariableDropDownLabel.Visible=true;
                app.CAxesVariableDropDown.Visible=true;
                app.ColorbarLabelEditField.Visible=true;
                app.ColorbarLabelEditFieldLabel.Visible=true;

                app.CScaleDropDown.Visible=true;
                app.CScaleDropDownLabel.Visible=true;

            else
                app.Panel_2.Visible=false;
                app.CAxesVariableDropDownLabel.Visible=false;
                app.CAxesVariableDropDown.Visible=false;
                app.ColorbarLabelEditField.Visible=false;
                app.ColorbarLabelEditFieldLabel.Visible=false;
                app.CScaleDropDown.Visible=false;
                app.CScaleDropDownLabel.Visible=false;

            end


        end

        % Value changed function: XAxesVariableDropDown
        function XAxesVariableDropDownValueChanged(app, event)
            app.CustomXLabelEditField.Value=app.XAxesVariableDropDown.Value;
            VarSelectionTreeCheckedNodesChanged(app)
        end

        % Drop down opening function: XAxesVariableDropDown_2
        function XAxesVariableDropDown_2Opening(app, event)

            if   strcmp(app.PlotType_DropDown.Value,'XY Ratio A/B vs C/D') || strcmp(app.PlotType_DropDown.Value,'XYZ Ratio A/B vs C/D vs E/F')
                var2add={'1'};
            else
                var2add={'1:n'};
            end
            app.XAxesVariableDropDown_2.Items=[var2add app.UITable.ColumnName((any(cellfun(@isnumeric,table2cell(app.UITable.Data)))|any(cellfun(@islogical,table2cell(app.UITable.Data)))))'];


        end

        % Value changed function: XAxesVariableDropDown_2
        function XAxesVariableDropDown_2ValueChanged(app, event)
            if   strcmp(app.PlotType_DropDown.Value,'XY Ratio A/B vs C/D') || strcmp(app.PlotType_DropDown.Value,'XYZ Ratio A/B vs C/D vs E/F')
                app.XLabelEditField.Value = [app.XAxesVariableDropDown_2.Value '/' app.YAxesVariableDropDown.Value];
            else
                app.XLabelEditField.Value = app.XAxesVariableDropDown_2.Value;
            end
        end

        % Drop down opening function: YAxesVariableDropDown
        function YAxesVariableDropDownOpening(app, event)
            if   strcmp(app.PlotType_DropDown.Value,'XY Ratio A/B vs C/D') || strcmp(app.PlotType_DropDown.Value,'XYZ Ratio A/B vs C/D vs E/F')
                var2add={'1'};
            else
                var2add={'1:n'};
            end
            app.YAxesVariableDropDown.Items=[var2add app.UITable.ColumnName((any(cellfun(@isnumeric,table2cell(app.UITable.Data)))|any(cellfun(@islogical,table2cell(app.UITable.Data)))))'];

        end

        % Value changed function: YAxesVariableDropDown
        function YAxesVariableDropDownValueChanged(app, event)
            if   strcmp(app.PlotType_DropDown.Value,'XY Ratio A/B vs C/D') || strcmp(app.PlotType_DropDown.Value,'XYZ Ratio A/B vs C/D vs E/F')
                app.XLabelEditField.Value = [app.XAxesVariableDropDown_2.Value '/' app.YAxesVariableDropDown.Value];
            else
                app.YLabelEditField.Value = app.YAxesVariableDropDown.Value;
            end

        end

        % Drop down opening function: ZAxesVariableDropDown
        function ZAxesVariableDropDownOpening(app, event)
            if   strcmp(app.PlotType_DropDown.Value,'XY Ratio A/B vs C/D') || strcmp(app.PlotType_DropDown.Value,'XYZ Ratio A/B vs C/D vs E/F')
                var2add={'1'};
            else
                var2add={'1:n'};
            end
            app.ZAxesVariableDropDown.Items=[var2add app.UITable.ColumnName((any(cellfun(@isnumeric,table2cell(app.UITable.Data)))|any(cellfun(@islogical,table2cell(app.UITable.Data)))))'];

        end

        % Value changed function: ZAxesVariableDropDown
        function ZAxesVariableDropDownValueChanged(app, event)
            if   strcmp(app.PlotType_DropDown.Value,'XY Ratio A/B vs C/D') || strcmp(app.PlotType_DropDown.Value,'XYZ Ratio A/B vs C/D vs E/F')
                app.YLabelEditField.Value = [app.ZAxesVariableDropDown.Value '/' app.DAxesVariableDropDown.Value];
            else
                app.ZLabelEditField.Value = app.ZAxesVariableDropDown.Value;
            end


        end

        % Drop down opening function: CAxesVariableDropDown
        function CAxesVariableDropDownOpening(app, event)
            app.CAxesVariableDropDown.Items=[{'1:n'} app.UITable.ColumnName((any(cellfun(@isnumeric,table2cell(app.UITable.Data)))|any(cellfun(@islogical,table2cell(app.UITable.Data)))))'];
        end

        % Value changed function: CAxesVariableDropDown
        function CAxesVariableDropDownValueChanged(app, event)
            app.ColorbarLabelEditField.Value = app.CAxesVariableDropDown.Value;
        end

        % Drop down opening function: DAxesVariableDropDown
        function DAxesVariableDropDownOpening(app, event)
            if   strcmp(app.PlotType_DropDown.Value,'XY Ratio A/B vs C/D') || strcmp(app.PlotType_DropDown.Value,'XYZ Ratio A/B vs C/D vs E/F')
                var2add={'1'};
            else
                var2add={'1:n'};
            end
            app.DAxesVariableDropDown.Items=[var2add app.UITable.ColumnName((any(cellfun(@isnumeric,table2cell(app.UITable.Data)))|any(cellfun(@islogical,table2cell(app.UITable.Data)))))'];
        end

        % Value changed function: DAxesVariableDropDown
        function DAxesVariableDropDownValueChanged(app, event)

             if   strcmp(app.PlotType_DropDown.Value,'XY Ratio A/B vs C/D') || strcmp(app.PlotType_DropDown.Value,'XYZ Ratio A/B vs C/D vs E/F')
                app.YLabelEditField.Value = [app.ZAxesVariableDropDown.Value '/' app.DAxesVariableDropDown.Value];
            else
                app.DLabelEditField.Value = app.DAxesVariableDropDown.Value;
            end

        end

        % Drop down opening function: EAxesVariableDropDown
        function EAxesVariableDropDownOpening(app, event)
             if   strcmp(app.PlotType_DropDown.Value,'XY Ratio A/B vs C/D') || strcmp(app.PlotType_DropDown.Value,'XYZ Ratio A/B vs C/D vs E/F')
                var2add={'1'};
            else
                var2add={'1:n'};
            end
            app.EAxesVariableDropDown.Items=[var2add app.UITable.ColumnName((any(cellfun(@isnumeric,table2cell(app.UITable.Data)))|any(cellfun(@islogical,table2cell(app.UITable.Data)))))'];
        end

        % Value changed function: EAxesVariableDropDown
        function EAxesVariableDropDownValueChanged(app, event)
                app.ZLabelEditField.Value = [app.EAxesVariableDropDown.Value '/' app.FAxesVariableDropDown.Value];
           
        end

        % Drop down opening function: FAxesVariableDropDown
        function FAxesVariableDropDownOpening(app, event)
             if   strcmp(app.PlotType_DropDown.Value,'XY Ratio A/B vs C/D') || strcmp(app.PlotType_DropDown.Value,'XYZ Ratio A/B vs C/D vs E/F')
                var2add={'1'};
            else
                var2add={'1:n'};
            end
            app.FAxesVariableDropDown.Items=[var2add app.UITable.ColumnName((any(cellfun(@isnumeric,table2cell(app.UITable.Data)))|any(cellfun(@islogical,table2cell(app.UITable.Data)))))'];
        end

        % Value changed function: FAxesVariableDropDown
        function FAxesVariableDropDownValueChanged(app, event)
                app.ZLabelEditField.Value = [app.EAxesVariableDropDown.Value '/' app.FAxesVariableDropDown.Value];
            
        end

        % Value changed function: CustomColormapCheckBox
        function CustomColormapCheckBoxValueChanged(app, event)
            if  app.CustomColormapCheckBox.Value==true
                app.Panel_2.Visible=true;
                app.CAxesVariableDropDownLabel.Visible=true;
                app.CAxesVariableDropDown.Visible=true;
                app.ColorbarLabelEditField.Visible=true;
                app.ColorbarLabelEditFieldLabel.Visible=true;

                app.CScaleDropDown.Visible=true;
                app.CScaleDropDownLabel.Visible=true;

            else
                app.Panel_2.Visible=false;
                app.CAxesVariableDropDownLabel.Visible=false;
                app.CAxesVariableDropDown.Visible=false;
                app.ColorbarLabelEditField.Visible=false;
                app.ColorbarLabelEditFieldLabel.Visible=false;
                app.CScaleDropDown.Visible=false;
                app.CScaleDropDownLabel.Visible=false;

            end
        end

        % Button pushed function: ResetMask
        function ResetMaskPushed(app, event)
            app.UITable.Data.Active=true(size(app.UITable.Data.Active,1),1);

        end

        % Button pushed function: InvertMask
        function InvertMaskPushed(app, event)
            app.UITable.Data.Active=not(app.UITable.Data.Active);

        end

        % Button pushed function: Add2Mask
        function Add2MaskPushed(app, event)
            if not(isempty( app.UITable.Selection))
                app.UITable.Data.Active(unique(app.UITable.Selection(:,1)))=true;
            end
        end

        % Button pushed function: RemoveFromMask
        function RemoveFromMaskPushed(app, event)
            if not(isempty( app.UITable.Selection))
                app.UITable.Data.Active(unique(app.UITable.Selection(:,1)))=false;
            end
        end

        % Drop down opening function: LayoutDropDown
        function LayoutDropDownOpening(app, event)
            if app.UseDataSubdivisionCheckBox.Value==true && not(isempty(app.ListBox.Value)) %%
                app.LayoutDropDown.Items={'Stacked','Tiled','Individual'};
            else
                app.LayoutDropDown.Items={'Stacked','Tiled'};
            end
        end

        % Value changed function: FigureFontSizeSpinner, 
        % FigureFontSizeSpinner_3, FigureFontSizeSpinner_4
        function FigureFontSizeSpinnerValueChanged(app, event)

            value = event.Value;

            app.FigureFontSizeSpinner.Value=value;
            app.FigureFontSizeSpinner_3.Value=value;
            app.FigureFontSizeSpinner_4.Value=value;

        end

        % Button pushed function: NormalisedTEPlotButton
        function NormalisedTEPlotButtonPushed(app, event)
            if isempty(app.UITable.Data)
                message={'Empty Table!'};
                uialert(app.VisModule_GUI,message,'Error');
                return
            end

            settings.which='custom_TraceElements';
            settings.type.Value='custom_TraceElements';

            settings.NewPlot=true;
            settings.SubDiv=app.UseDataSubdivisionCheckBox.Value;

            send_to_EasyPlot(app,settings)

        end

        % Value changed function: ListBox_3
        function ListBox_3ValueChanged(app, event)
            T=app.UITable.Data;
            T=T(T.Active,:);


            if ismember(app.ListBox_3.Value,T.Properties.VariableNames)
                [x, ~]=  unique(T.(char(app.ListBox_3.Value)),'stable');
                x(cellfun(@isempty, x))={' '};

                try
                    app.ListBox_4.Items=x;
                    app.ListBox_4.Value=x;
                catch

                end
                app.ListBox_4.Visible=true;


            else
                app.ListBox_4.Items={};
            end


        end

        % Button pushed function: UserNormTraceElementPlotButton
        function UserNormTraceElementPlotButtonPushed(app, event)
            if isempty(app.UITable.Data)
                message={'Empty Table!'};
                uialert(app.VisModule_GUI,message,'Error');
                return
            end

            [settings]=plot_TE_fun([],[]);

            value = app.NormalisetoDropDown.Value;


            settings.which='custom_TraceElements';
            settings.type.Value=['custom_TraceElements' char(value)];

            settings.NewPlot=true;
            settings.SubDiv=app.UseDataSubdivisionCheckBox.Value;

            T=app.UITable.Data(app.UITable.Data.Active,:);
            T=T(contains(T.(char(app.ListBox_3.Value)),app.ListBox_4.Value),any(cellfun(@isnumeric,table2cell(T))));

            settings.TENorm.NormState=true;

            if app.FilterElementsCheckBox.Value==true && startsWith(app.DefaultsDropDown.Value,'Mass')
                Elements=app.MassList;
                headers= T.Properties.VariableNames;
            else
                Elements=app.ElementList;
                headers= replace(T.Properties.VariableNames,{'0','1','2','3','4','5','6','7','8','9','_mat_ugg'},'');
            end

            T=T(:,ismember(headers,Elements));
            T.Properties.VariableNames=matlab.lang.makeUniqueStrings(headers(ismember(headers,Elements)));
            mean_data=mean(table2array(T),1,'omitnan');

            if app.FilterElementsCheckBox.Value==false || isempty(app.ElementstoPlotListBox.Value) && isempty(app.ElementstoPlotListBox.Items)
                settings.Element_List= T.Properties.VariableNames;
            elseif  isempty(app.ElementstoPlotListBox.Value)
                settings.Element_List=app.ElementstoPlotListBox.Items;
            else
                settings.Element_List=app.ElementstoPlotListBox.Items(find(ismember(app.ElementstoPlotListBox.Items,app.ElementstoPlotListBox.Value)));
            end

            VarNames=T.Properties.VariableNames;
            % mean_data_T=array2table(mean_data,'VariableNames',thx.Properties.VariableNames);


            settings.TENorm.Values=mean_data;
            settings.TENorm.Elements=VarNames;
            settings.TENorm.Name=app.ListBox_4.Value;


            send_to_EasyPlot(app,settings)


        end

        % Value changed function: UserNormCheckBox
        function UserNormCheckBoxValueChanged(app, event)
            %ismember(app.ListBox.Value,app.UITable.Data.Properties.VariableNames)
            if isempty(app.UITable.Data)
                app.ListBox_3.Items={};
                return
            end
            value = app.UserNormCheckBox.Value;

            app.ListBox_3.Visible=app.UserNormCheckBox.Value;
            app.ListBox_4.Visible=app.UserNormCheckBox.Value;
            app.UserNormTraceElementPlotButton.Visible=app.UserNormCheckBox.Value;

            app.SaveNormDataButton.Visible=app.UserNormCheckBox.Value;
            app.SigDigitsSpinner.Visible=app.UserNormCheckBox.Value;
            app.SigDigitsSpinnerLabel.Visible=app.UserNormCheckBox.Value;

            T=app.UITable.Data;
            T=T(T.Active,:);

            app.ListBox_3.Items=app.UITable.ColumnName(not(any(cellfun(@isnumeric,table2cell(T)))|any(cellfun(@islogical,table2cell(T)))));
            if ismember(app.ListBox_3.Value,T.Properties.VariableNames)
                [x, ~]=  unique(T.(char(app.ListBox_3.Value)),'stable');
                x(cellfun(@isempty, x))={' '};


                if app.UseDataSubdivisionCheckBox.Value==false
                    app.ListBox_4.Visible=false;
                    app.ListBox_4.Items={};
                else
                    try
                        app.ListBox_4.Items=x;
                        app.ListBox_4.Value=x;
                    catch

                    end

                end
            else
                app.ListBox_4.Items={};
            end
        end

        % Value changed function: ListBox_4
        function ListBox_4ValueChanged(app, event)


            [settings]=plot_TE_fun([],[]);


            settings.which='custom_TraceElements';
            settings.type.Value='custom_TraceElements';

            settings.NewPlot=true;
            settings.SubDiv=app.UseDataSubdivisionCheckBox.Value;

            T=app.UITable.Data(app.UITable.Data.Active,:);
            T=T(contains(T.(char(app.ListBox_3.Value)),app.ListBox_4.Value),any(cellfun(@isnumeric,table2cell(T))));

            if app.FilterElementsCheckBox.Value==false || isempty(app.ElementstoPlotListBox.Value) && isempty(app.ElementstoPlotListBox.Items)
                headers= replace(T.Properties.VariableNames,{'0','1','2','3','4','5','6','7','8','9','_mat_ugg'},'');
                headers=headers(ismember(headers,app.ElementList));
                settings.Element_List= headers;

            elseif  isempty(app.ElementstoPlotListBox.Value)
                settings.Element_List=app.ElementstoPlotListBox.Items;
            else
                settings.Element_List=app.ElementstoPlotListBox.Items(find(ismember(app.ElementstoPlotListBox.Items,app.ElementstoPlotListBox.Value)));
            end


            %settings.Element_List=app.ElementstoPlotListBox.Items;

            settings.TENorm.NormState=true;


            if app.FilterElementsCheckBox.Value==true && startsWith(app.DefaultsDropDown.Value,'Mass')
                Elements=app.MassList;
                headers= T.Properties.VariableNames;
            else
                Elements=app.ElementList;
                headers= replace(T.Properties.VariableNames,{'0','1','2','3','4','5','6','7','8','9','_mat_ugg'},'');
            end

            T=T(:,ismember(headers,Elements));
            T.Properties.VariableNames=matlab.lang.makeUniqueStrings(headers(ismember(headers,Elements)));


            mean_data=mean(table2array(T));
            VarNames=T.Properties.VariableNames;
            % mean_data_T=array2table(mean_data,'VariableNames',thx.Properties.VariableNames);


            settings.TENorm.Values=mean_data;
            settings.TENorm.Elements=VarNames;
            settings.TENorm.Name=app.ListBox_4.Value;


        end

        % Value changed function: FilterElementsCheckBox
        function FilterElementsCheckBoxValueChanged(app, event)
            value = app.FilterElementsCheckBox.Value;
            app.ElementstoPlotListBox.Visible=value;
            app.DropDown_2.Visible=value;
            app.PlotDetectionLimitCheckBox.Visible=value;
            app.TraceElementPlotButton.Visible=value;
            app.DefaultsDropDown.Visible=value;
            app.DefaultsDropDownLabel.Visible=value;
            DefaultsDropDownValueChanged(app)
        end

        % Value changed function: DefaultsDropDown
        function DefaultsDropDownValueChanged(app, event)

            switch  app.DefaultsDropDown.Value

                case'All Elements'
                    Elements=app.ElementList;

                    app.ElementstoPlotListBox.Items=Elements;

                case'REE'
                    app.ElementstoPlotListBox.Items={'La', 'Ce', 'Pr', 'Nd', 'Sm', 'Eu', 'Gd', 'Tb', 'Dy', 'Ho', 'Er', 'Tm', 'Yb', 'Lu'};
                case'REE (Ionic Radii)'
                    app.ElementstoPlotListBox.Items={'La', 'Ce', 'Pr', 'Nd', 'Sm', 'Eu', 'Gd', 'Tb', 'Dy', 'Ho', 'Er', 'Tm', 'Yb', 'Lu'};
                case'Trace Elements'
                    app.ElementstoPlotListBox.Items={'Li','Be','Na','Mg','Al','Si','K','Ca','Sc','Ti','V','Cr','Mn','Fe','Co','Ni','Cu','Zn','As','Rb','Sr','Y','Zr','Nb','Mo','Ag','In','Cs','Ba','La','Ce','Pr','Nd','Sm','Eu','Gd','Tb','Dy','Ho','Er','Tm','Yb','Lu','Hf','Ta','W','Pb','Bi','U'};
                case 'Spider'
                    app.ElementstoPlotListBox.Items={ 'Th','U','Nb','La','Ce','Sr','P','Nd','Zr','Hf','Sm','Eu','Tb','Ti','Y','Yb','Lu'};
                case 'Incompatible Trace Elements'
                    app.ElementstoPlotListBox.Items={ 'Rb','Ba','Th','U','K','Ta','Nb','La','Ce','Sr','Nd','P','Sm','Zr','Hf','Eu','Ti','Tb','Y','Yb','Lu'};
                case {'Elements in table','Element data in table'}
                    if ~isempty(app.UITable.Data)
                        Elements=app.ElementList;

                        T=app.UITable.Data;
                        %T=T(T.Active,:);

                        headers= replace(T.Properties.VariableNames,{'0','1','2','3','4','5','6','7','8','9','_mat_ugg'},'');
                        T=T(:,ismember(headers,Elements));

                        Element_Names=headers(ismember(headers,app.ElementList));%;


                        app.ElementstoPlotListBox.Items=app.ElementList(ismember(app.ElementList,Element_Names));

                        if strcmp(app.DefaultsDropDown.Value,'Element data in table')

                            if app.UseDataSubdivisionCheckBox.Value==true
                                T=T(app.UITable.Data.Active & contains(app.UITable.Data.(char(app.ListBox.Value)),app.ListBox_2.Value),:);
                            end
                            %  [T, T_DL] = FindDetection_Limits2(app, T);

                            %    headers=headers(ismember(headers,Elements));
                            %   headers=headers(any(cellfun(@isnumeric,table2cell(T))));
                            %

                            %                         T(:,not(sum(table2array(T(:,any(cellfun(@isnumeric,table2cell(T)))))==0)==size(T,1)));


                            app.ElementstoPlotListBox.Items=replace(T(:,not(sum(table2array(T(:,any(cellfun(@isnumeric,table2cell(T)))))==0)==size(T,1))).Properties.VariableNames,{'0','1','2','3','4','5','6','7','8','9','_mat_ugg'},'');
                        end
                    else
                        app.ElementstoPlotListBox.Items={};
                    end


                case {'Masses in table','Mass data in table','Mass data in table ~0'}

                    if ~isempty(app.UITable.Data)

                        T=app.UITable.Data;
                        T=T(T.Active,:);
                        headers= replace(T.Properties.VariableNames,{'_mat_ugg'},'');
                        T=T(:,ismember(headers,app.MassList));


                        Element_Names=headers(ismember(headers,app.MassList));%;


                        app.ElementstoPlotListBox.Items = app.MassList(ismember(app.MassList,Element_Names));

                        if strcmp(app.DefaultsDropDown.Value,'Mass data in table')

                            if app.UseDataSubdivisionCheckBox.Value==true
                                T=T(contains(app.UITable.Data.(char(app.ListBox.Value))(app.UITable.Data.Active),app.ListBox_2.Value),:);
                            end

                            %    headers=headers(ismember(headers,app.MassList));
                            %   headers=headers(any(cellfun(@isnumeric,table2cell(T))));
                            %

                            %                         T(:,not(sum(table2array(T(:,any(cellfun(@isnumeric,table2cell(T)))))==0)==size(T,1)));
                            headers=T(:,not(sum(table2array(T(:,any(cellfun(@isnumeric,table2cell(T)))))==0)==size(T,1))).Properties.VariableNames;

                        elseif strcmp(app.DefaultsDropDown.Value,'Mass data in table ~0')
                            if app.UseDataSubdivisionCheckBox.Value==true
                                T=T(contains(app.UITable.Data.(char(app.ListBox.Value))(app.UITable.Data.Active),app.ListBox_2.Value),:);
                            end

                            A = table2array(T(:, varfun(@isnumeric, T, 'OutputFormat','uniform')));
                            V = T.Properties.VariableNames(varfun(@isnumeric, T, 'OutputFormat','uniform'));
                            headers= V(~(all(A == 0 | isnan(A))));

                        end
                        headers= replace(headers,{'_mat_ugg'},'');
                        masses_names=headers(ismember(headers,app.MassList));%;

                        headers= app.MassList(ismember(app.MassList,masses_names));
                        app.ElementstoPlotListBox.Items=headers;

                        %case 'Custom'
                    else
                        app.ElementstoPlotListBox.Items={};
                    end

            end

            app.ElementstoPlotListBox.Value=app.ElementstoPlotListBox.Items;

        end

        % Button pushed function: TraceElementPlotButton
        function TraceElementPlotButtonPushed(app, event)
            % make a get table fun for TE Plots with LOD handling and
            % filtering make LODs as own group with name_LOD with grey -
            %

            if isempty(app.UITable.Data)
                message={'Empty Table!'};
                uialert(app.VisModule_GUI,message,'Error');
                return
            end
            settings.which='custom_TraceElements';
            settings.type.Value='custom_TraceElements';
            T=app.UITable.Data(1,:);


            if any(endsWith(app.UITable.Data.Properties.VariableNames,'_LOD'))

                if strcmp('','Plot only data above LOD')

                elseif strcmp('','Plot data above LOD + LOD') % (min max mean median all)

                elseif strcmp('','Plot all data ignoring LOD (This is dangerous!)')
                    % elseif strcmp('','Plot data above and below LOD + LOD') % (min max mean median all)
                    % maybe in the future data subduvision needs to be duplicated
                    % in grey symbols with _below_LOD and grey.
                end

            end

            table_filter=true(size(T,2),1);

            if app.FilterElementsCheckBox.Value==false || isempty(app.ElementstoPlotListBox.Value) && isempty(app.ElementstoPlotListBox.Items)
                headers= replace(T.Properties.VariableNames,{'0','1','2','3','4','5','6','7','8','9','_mat_ugg'},'');
                table_filter=ismember(headers,app.ElementList);
                headers=headers(table_filter);
                settings.Element_List= headers;

            elseif  isempty(app.ElementstoPlotListBox.Value)
                settings.Element_List=app.ElementstoPlotListBox.Items;
            else
                table_filter=ismember(app.ElementstoPlotListBox.Items,app.ElementstoPlotListBox.Value);
                settings.Element_List=app.ElementstoPlotListBox.Items(find(table_filter));
            end

            if strcmp(app.DefaultsDropDown.Value,'REE (Ionic Radii)')
                x=[1 1.01466 1.02975 1.04529 1.07357 1.08650 1.09975 1.11333 1.12725 1.14042 1.15278 1.16426 1.17480 1.18434];
                if not(isempty(app.ElementstoPlotListBox.Value))
                    settings.xValues=x(ismember(app.ElementstoPlotListBox.Items,app.ElementstoPlotListBox.Value));
                else
                    settings.xValues=x;
                end
            end
            settings.NewPlot=true;
            settings.SubDiv=app.UseDataSubdivisionCheckBox.Value;
            settings.ylabel.String='[μg/g]';


            if app.PlotDetectionLimitCheckBox.Value
                settings.DetectionLimits.PlotDetectionLimits=true;
                try
                    T_DL=app.DetectionLimitsTable(:,ismember(app.DetectionLimitsTable.Properties.VariableNames,app.UITable.Data.Properties.VariableNames));
                    settings.DetectionLimits.DetectionLimitsTable=app.DetectionLimitsTable;
                    %   switch app.DropDown_2.Value
                    %                         case 'All'
                    %                             settings.DetectionLimits.DetectionLimitsTable=app.DetectionLimitsTable;
                    %                         case 'Maximum'
                    %                             T_DL=array2table(max(table2array(T_DL)));
                    %                             T_DL.Properties.VariableNames=app.DetectionLimitsTable.Properties.VariableNames;
                    %
                    %                             settings.DetectionLimits.DetectionLimitsTable=T_DL;
                    %                         case 'Minimum'
                    %
                    %                             T_DL=array2table(min(table2array(T_DL)));
                    %                             T_DL.Properties.VariableNames=app.DetectionLimitsTable.Properties.VariableNames;
                    %
                    %                             settings.DetectionLimits.DetectionLimitsTable=T_DL;
                    %                         case 'Range'
                    %
                    %                             T_DL=array2table([max(table2array(T_DL));min(table2array(T_DL));]);
                    %                             T_DL.Properties.VariableNames=app.DetectionLimitsTable.Properties.VariableNames;
                    %
                    %                             settings.DetectionLimits.DetectionLimitsTable=T_DL;
                    %                         case 'Mean'
                    %
                    %                             T_DL=array2table(mean(table2array(T_DL),"omitnan"));
                    %                             T_DL.Properties.VariableNames=app.DetectionLimitsTable.Properties.VariableNames;
                    %
                    %                             settings.DetectionLimits.DetectionLimitsTable=T_DL;
                    %
                    %                         case 'Median'
                    %
                    %                             T_DL=array2table(median(table2array(T_DL),"omitnan"));
                    %                             T_DL.Properties.VariableNames=app.DetectionLimitsTable.Properties.VariableNames;
                    %
                    %                             settings.DetectionLimits.DetectionLimitsTable=T_DL(table_filter);
                    %                     end

                    settings.DetectionLimits.DetectionLimitsTable(:,ismember(settings.DetectionLimits.DetectionLimitsTable.Properties.VariableNames,settings.Element_List));

                catch ME
                    keyboard
                end
            end


            send_to_EasyPlot(app,settings)


        end

        % Button pushed function: GeoCPlotButton
        function GeoCPlotButtonPushed(app, event)
            if app.UseDataSubdivisionCheckBox.Value==true
                T=app.UITable.Data(contains(app.UITable.Data.(char(app.ListBox.Value)),app.ListBox_2.Value),:);
            else
                T=app.UITable.Data;
            end

            T=T(T.Active,:);

            if not(isempty(T))
                GeoCPlot(T)
            end

        end

        % Button pushed function: SaveNormDataButton
        function SaveNormDataButtonPushed(app, event)
            if isempty(app.UITable.Data)
                message={'Empty Table!'};
                uialert(app.VisModule_GUI,message,'Error');
                return
            end

            if isempty(app.ListBox_4.Value)
                return
            end


            T=app.UITable.Data(app.UITable.Data.Active,:);
            T=T(contains(T.(char(app.ListBox_3.Value)),app.ListBox_4.Value),any(cellfun(@isnumeric,table2cell(T))));


            settings.TENorm.NormState=true;

            if app.FilterElementsCheckBox.Value==true && startsWith(app.DefaultsDropDown.Value,'Mass')
                Elements=app.MassList;
                headers= T.Properties.VariableNames;
            else
                Elements=app.ElementList;
                headers= replace(T.Properties.VariableNames,{'0','1','2','3','4','5','6','7','8','9','_mat_ugg'},'');
            end

            T=T(:,ismember(headers,Elements));
            T.Properties.VariableNames=matlab.lang.makeUniqueStrings(headers(ismember(headers,Elements)));

            mean_data=mean(table2array(T),1,'omitnan');
            VarNames=T.Properties.VariableNames;

            message=replace(['> ' char(app.ListBox_3.Value) ' - ' char(app.ListBox_4.Value) newline char(join(VarNames,' ')) newline mat2str(round(mean_data,app.SigDigitsSpinner.Value))],{'[',']'},'');

            try
                filename='TraceElements_UserNorm.txt';

                if isdeployed && not(isfield(app.MinPlotX,'Standalone'))
                    filename= fullfile(app.MinPlotX.installpath,'Dev',filename);
                else
                    filename=which('TraceElements_UserNorm.txt');
                end

                if exist(filename, 'file') ~= 2
                    fid = fopen(filename, 'w');
                    if fid == -1
                        error('Failed to create file.');
                    end
                    fprintf(fid, '%s\n', message);
                    fclose(fid);
                else
                    fileContents = fileread(filename);
                    if ~contains(fileContents, message)
                        % Append the message at the end

                        fid = fopen(filename, 'a');
                        if fid == -1
                            error('Failed to open file for appending.');
                        end
                        fprintf(fid, '%s\n', message);
                        fclose(fid);

                    else
                        ans=  uiconfirm(app.VisModule_GUI,{'Norm data already exists','Do you want to overwirte it?'},'Norm data already exists');
                        if ~strcmp(ans,'OK')
                            return
                        end
                    end
                end


                ReadTEData(app)

                ReadTEData(app,filename)

                uialert(app.VisModule_GUI,[char(app.ListBox_4.Value) ' norm data stored!'],'Wohoo!','Icon','success');
            catch ME
                DisplayError(app, ME,'Save Trace Element User Norm Data')

                % Create a UIFigure
                hfig = uifigure;
                CenterFig_fun(hfig)
                % Create a grid layout with 3 rows and 3 columns
                grid = uigridlayout(hfig, [3, 3]);

                % Set row heights and column widths
                grid.RowHeight = {25, '1x', 45};  % First row takes the height of text, second row auto, third row takes button height
                grid.ColumnWidth = {'1x', 100, 55};  % Equal column width for 3 columns

                % Create a label in the first row spanning all 3 columns
                lbl = uilabel(grid);
                lbl.Text = 'Copy the string and add it to the file: /installationpath/MinPlotX/application/Dev/TraceElements_UserNorm.txt';
                lbl.Layout.Row = 1;
                lbl.Layout.Column = [1, 3];  % Spans across all columns

                % Create a textarea in the second row, spanning all 3 columns
                t = uitextarea(grid);
                t.Value = {message};  % Default value
                t.Layout.Row = 2;
                t.Layout.Column = [1, 3];  % Spans across all columns

                % Create a grid for the third row to hold the buttons (2 buttons in 3 columns)
                buttonGrid = uigridlayout(grid, [1, 3]);
                buttonGrid.Layout.Row = 3;
                buttonGrid.Layout.Column = [1, 3];  % Span the entire third row

                % Create the "Copy to clipboard" button
                copyBtn = uibutton(buttonGrid, 'Text', 'Copy to Clipboard');
                copyBtn.Layout.Column = 1;  % First column in the button grid

                % Create the "Close" button
                closeBtn = uibutton(buttonGrid, 'Text', 'Close');
                closeBtn.Layout.Column = 3;  % Third column in the button grid


                % Add button callbacks (example)
                copyBtn.ButtonPushedFcn = @(src, event) disp('Copied to clipboard');
                closeBtn.ButtonPushedFcn = @(src, event) close(hfig);


                % Callback for "Copy to Clipboard" button
                copyBtn.ButtonPushedFcn = @(src, event) clipboard('copy', char(join(t.Value(:),newline)));
                % Callback for "Close" button
                closeBtn.ButtonPushedFcn = @(src, event) close(hfig);

                % if isdeployed && not(isfield(app.MinPlotX,'Standalone'))
                %     [file,location] = uiputfile(fullfile(app.MinPlotX.installpath,'Dev'),'User Norm',filename)
                % end

            end


        end

        % Button pushed function: LoadTableButton
        function LoadTableButtonPushed(app, event)
            f = figure('Renderer', 'painters', 'Position', [-100 -100 0 0]);

            [FileName, FilePath] = uigetfile({'*.xlsx; *.xls; *.csv; *.txt'}, 'Pick a file');
            delete(f);
            figure(app.VisModule_GUI);

            T=  readtable(fullfile(FilePath,FileName));
            startupFcn(app, T)
        end

        % Button pushed function: UpdateNormListButton
        function UpdateNormListButtonPushed(app, event)
            ReadTEData(app)

            if isdeployed && not(isfield(app.MinPlotX,'Standalone'))
                file= fullfile(app.MinPlotX.installpath,'Dev','TraceElements_UserNorm.txt');
            else
                file=which('TraceElements_UserNorm.txt');
            end
            if not(isempty(file))
                ReadTEData(app,file)
            end
        end

        % Value changed function: AnnotationCheckBox, AnnotationCheckBox_2
        function AnnotationCheckBoxValueChanged(app, event)
            if exist("event","var")
                value = event.Value;
            else
                value=app.AnnotationCheckBox.Value;
            end
            app.AnnotationCheckBox.Value=value;
            app.AnnotationCheckBox_2.Value=value;
            app.AnnotationListBox.Visible=value;
            app.AnnotationListBox_TE.Visible=value;

            app.AnnotationListBox.Items=app.UITable.ColumnName(not(any(cellfun(@islogical,table2cell(app.UITable.Data)))))';
            app.AnnotationListBox_TE.Items=app.AnnotationListBox.Items;

        end

        % Value changed function: AnnotationListBox, AnnotationListBox_TE
        function AnnotationListBox_ValueChanged(app, event)
            value = event.Value;
            app.AnnotationListBox.Value=value;
            app.AnnotationListBox_TE.Value=value;

        end

        % Value changed function: ScaleDropDown
        function ScaleDropDownValueChanged(app, event)
            value = app.ScaleDropDown.Value;
            if strcmp(app.ScaleDropDown.Value,'All Linear')
                app.XScaleDropDown.Value='linear';
                app.YScaleDropDown.Value='linear';
                app.ZScaleDropDown.Value='linear';
                app.CScaleDropDown.Value='linear';

            elseif strcmp(app.ScaleDropDown.Value,'All Log')
                app.XScaleDropDown.Value='log';
                app.YScaleDropDown.Value='log';
                app.ZScaleDropDown.Value='log';
                app.CScaleDropDown.Value='log';
            end
        end

        % Value changed function: CScaleDropDown, XScaleDropDown, 
        % YScaleDropDown, ZScaleDropDown
        function XYZCScaleDropDownValueChanged(app, event)
            if numel(unique({app.XScaleDropDown.Value;...
                    app.YScaleDropDown.Value;...
                    app.ZScaleDropDown.Value;...
                    app.CScaleDropDown.Value}))==1
                if strcmp(app.XScaleDropDown.Value,'log')
                    app.ScaleDropDown.Value='All Log';
                else
                    app.ScaleDropDown.Value='All Linear';
                end
            else
                app.ScaleDropDown.Value='All Linear';
            end
        end

        % Close request function: VisModule_GUI
        function VisModule_GUICloseRequest(app, event)

            if ~isdeployed &&~isnumeric(app.d)&& ~isempty(app.d) && isvalid(app.d)
                delete(app.d)
            else
                delete(app)
            end
        end

        % Button pushed function: MaskSelectionButton
        function MaskSelectionButtonPushed(app, event)
            old_mask=app.UITable.Data.Active;
            value = app.MethodDropDown.Value;
            if any(sum(app.ClassTable.Data.Active)==numel(app.ClassTable.Data.Active)) &&not(isempty(app.ClassTable.Selection))
                selection=unique(app.ClassTable.Selection(:,1));
            else
                selection=find(app.ClassTable.Data.Active);
            end
            switch value
                case 'Unique String'
                    %str2find=app.ClassTable.Data.Name(app.ClassTable.Data.Active);
                    where=          app.UITable.Data.(char(app.VariableDropDown.Value));
                    classNumber=nan(size(where));
                    c=0;
                    for n=1:numel(app.ClassTable.Data.Name)
                        if app.ClassTable.Data.Active(n)==true
                            c=c+1;
                            classNumber(ismember(where,app.ClassTable.Data.Name(n)))=  c;
                        end
                    end
                    %                     set2nan=~ismember(where,app.ClassTable.Data.Name(app.ClassTable.Data.Active));
                    %                     classNumber(set2nan)=nan;
                    new_mask=ismember(where,app.ClassTable.Data.Name(selection));
                case 'Numeric'
                    where= app.UITable.Data.(char(app.VariableDropDown.Value));
                    if iscell(where)
                        where=[where{:}]';
                    end


                    new_mask=ismember(where,app.ClassTable.Data.Value(selection));

                case 'NumClasses'
                    try
                        mapnr=1;
                        ClassMapNr=find(app.Data.Classes(mapnr).n_classes==app.ClassesSpinner.Value);
                        if isempty(ClassMapNr)
                            app.MaxClassesSpinner.Value=app.ClassesSpinner.Value;
                            app.MinClassesSpinner.Value=2;
                            RunClassTestButtonPushed(app)
                            ClassMapNr=find(app.Data.Classes(mapnr).n_classes==app.ClassesSpinner.Value);
                        end
                        classNumber=app.Data.Classes(mapnr).ClassMap(ClassMapNr).classNumber;
                    catch
                        disp('no classification')
                        [classNumber, ~, ~]= kmeans(DataIn,app.ClassesSpinner.Value, 'replicates',app.IterationsSpinner.Value);
                    end
                    new_mask=ismember(classNumber,app.ClassTable.Data.Value(selection));
            end


            %             app.UITable.Data.Active(new_mask)=true;
            %             app.UITable.Data.Active(new_mask)=true;

            app.UITable.Data.Active(:)=(old_mask==true&new_mask==true);
            app.UITable.Data.Active(:)=(old_mask==true&new_mask==true);


            ids =1:numel(app.ClassTable.Data.Active);
            app.ClassTable.Data.Active(selection)=true;
            app.ClassTable.Data.Active(not(ismember(ids,ids(selection))))=false;

            update_class_table(app)


        end

        % Button pushed function: UnmaskButton
        function UnmaskButtonPushed(app, event)
            old_mask=app.UITable.Data.Active;

            value = app.MethodDropDown.Value;
            if any(sum(app.ClassTable.Data.Active)==numel(app.ClassTable.Data.Active)) &&not(isempty(app.ClassTable.Selection))
                selection=unique(app.ClassTable.Selection(:,1));
            else
                if not(isempty(unique(app.ClassTable.Selection(:,1))))
                    app.ClassTable.Data.Active(unique(app.ClassTable.Selection(:,1)))=true;
                end
                selection=app.ClassTable.Data.Active;
            end
            switch value
                case 'Unique String'
                    %str2find=app.ClassTable.Data.Name(app.ClassTable.Data.Active);
                    where=          app.UITable.Data.(char(app.VariableDropDown.Value));
                    classNumber=nan(size(where));
                    c=0;
                    for n=1:numel(app.ClassTable.Data.Name)
                        if app.ClassTable.Data.Active(n)==true
                            c=c+1;
                            classNumber(ismember(where,app.ClassTable.Data.Name(n)))=  c;
                        end
                    end
                    %                     set2nan=~ismember(where,app.ClassTable.Data.Name(app.ClassTable.Data.Active));
                    %                     classNumber(set2nan)=nan;
                    new_mask=ismember(where,app.ClassTable.Data.Name(selection));
                case 'Numeric'
                    where= app.UITable.Data.(char(app.VariableDropDown.Value));
                    if iscell(where)
                        where=[where{:}]';
                    end


                    new_mask=ismember(where,app.ClassTable.Data.Value(selection));

                case 'NumClasses'
                    try
                        mapnr=1;
                        ClassMapNr=find(app.Data.Classes(mapnr).n_classes==app.ClassesSpinner.Value);
                        if isempty(ClassMapNr)
                            app.MaxClassesSpinner.Value=app.ClassesSpinner.Value;
                            app.MinClassesSpinner.Value=2;
                            RunClassTestButtonPushed(app)
                            ClassMapNr=find(app.Data.Classes(mapnr).n_classes==app.ClassesSpinner.Value);
                        end
                        classNumber=app.Data.Classes(mapnr).ClassMap(ClassMapNr).classNumber;
                    catch
                        disp('no classification')
                        [classNumber, ~, ~]= kmeans(DataIn,app.ClassesSpinner.Value, 'replicates',app.IterationsSpinner.Value);
                    end
                    new_mask=ismember(classNumber,app.ClassTable.Data.Value(selection));
            end


            %  mask=old_mask==true & not(old_mask==false & new_mask==true);
            app.UITable.Data.Active(new_mask)=true;
            app.UITable.Data.Active(new_mask)=true;

            update_class_table(app)

        end

        % Button pushed function: ResetMaskButton
        function ResetMaskButtonPushed(app, event)
            if not(isempty(app.ClassTable.Data))
                app.ClassTable.Data.Active(:)=true;
            end
            app.UITable.Data.Active(:)=true;
            app.UITable.Data.Active(:)=true;
            update_class_table(app)


        end

        % Value changed function: ExcludeEmptyCheckBox
        function ExcludeEmptyCheckBoxValueChanged(app, event)
            value = app.ExcludeEmptyCheckBox.Value;
            update_class_table(app)

            if app.ExcludeEmptyCheckBox.Value==true
                app.UnmaskButton.Visible=false;
            else
                app.UnmaskButton.Visible=true;
            end
        end

        % Button pushed function: SelectAllButton
        function SelectAllButtonPushed(app, event)
            if any(not(app.ClassTable.Data.Active(:)))
                app.ClassTable.Data.Active(:)=(true);
            else
                app.ClassTable.Data.Active(:)=(false);
            end
        end

        % Drop down opening function: VariableDropDown
        function VariableDropDownOpening(app, event)
            tic
            if isempty(app.UITable.Data)
                app.VariableDropDown.Items={};
                return
            end
            T_Thx=app.UITable.Data(app.UITable.Data.Active,:);

            T_Thx2=T_Thx(1,any(cellfun(@ischar,table2cell(T_Thx)))&not(sum(cellfun(@isempty,table2cell(T_Thx)))==size(T_Thx,1)));
            app.VariableDropDown.Items=T_Thx2.Properties.VariableNames;
            toc
        end

        % Value changed function: VariableDropDown
        function VariableDropDownValueChanged(app, event)
            %  value = app.VariableDropDown.Value;
            update_class_table(app)

        end

        % Button pushed function: SplitMatrixInclusionButton
        function SplitMatrixInclusionButtonPushed(app, event)
            app.d = uiprogressdlg(app.VisModule_GUI,'Title','Updating Table', ...
                'Message','Updating Table','Cancelable','off');

            if isempty(app.TableRaw)
                app.TableRaw = app.UITable.Data;
                app.DetectionLimitsTableRaw = app.DetectionLimitsTable;
            end
            T=split_table_fun(app,[app.UITable.Data(:,app.ListBox.Value) app.DetectionLimitsTable   ]);
            app.DetectionLimitsTable=T(:,2:end);
            T=split_table_fun(app, app.UITable.Data);

            app.UITable.Data = T;
            app.UITable.ColumnName = T.Properties.VariableNames;


            app.SplitMatrixInclusionButton.Visible=false;
            app.ResetButton.Visible=true;

            if ismember(app.ListBox.Value,app.UITable.Data.Properties.VariableNames)
                [x, ~]=  unique(app.UITable.Data.(char(app.ListBox.Value)),'stable');
                x(cellfun(@isempty, x))={' '};


                app.nEditField.Value=numel(x);

                if app.UseDataSubdivisionCheckBox.Value==false
                    app.ListBox_2.Visible=false;
                    app.ListBox_2.Items={};

                elseif numel(x)>50
                    app.ListBox_2.Visible=true;
                    app.ListBox_2.Items=x;
                    app.ListBox_2.Value=x(1:5);

                else
                    try
                        app.ListBox_2.Items=x;
                        app.ListBox_2.Value=x;
                    catch

                    end
                    app.ListBox_2.Visible=true;

                end
            else
                app.ListBox_2.Items={};
            end

            delete(app.d);


        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.d = uiprogressdlg(app.VisModule_GUI,'Title','Updating Table',...
                'Message','Updating Table','Cancelable','off');
            if ~isempty(app.TableRaw)
                app.UITable.Data=app.TableRaw;
                app.UITable.ColumnName=app.UITable.Data.Properties.VariableNames;
                app.TableRaw=[];

                app.DetectionLimitsTable=app.DetectionLimitsTableRaw;
                app.DetectionLimitsTableRaw=[];

            end
            app.ResetButton.Visible=false;
            app.SplitMatrixInclusionButton.Visible=true;


            UseDataSubdivisionCheckBoxValueChanged(app)

            if ismember(app.ListBox.Value,app.UITable.Data.Properties.VariableNames)
                [x, ~]=  unique(app.UITable.Data.(char(app.ListBox.Value)),'stable');
                x(cellfun(@isempty, x))={' '};


                app.nEditField.Value=numel(x);

                if app.UseDataSubdivisionCheckBox.Value==false
                    app.ListBox_2.Visible=false;
                    app.ListBox_2.Items={};

                elseif numel(x)>50
                    app.ListBox_2.Visible=true;
                    app.ListBox_2.Items=x;
                    app.ListBox_2.Value=x(1:5);

                else
                    try
                        app.ListBox_2.Items=x;
                        app.ListBox_2.Value=x;
                    catch

                    end
                    app.ListBox_2.Visible=true;

                end
            else
                app.ListBox_2.Items={};
            end
            delete(app.d)
        end

        % Button pushed function: CombineColumnsButton
        function CombineColumnsButtonPushed(app, event)
            if not(isempty(app.TableRaw))
                ResetButtonPushed(app)
            end
            CombineColHelper(app,app.UITable)

        end

        % Button pushed function: DataCalculatorModuleButton
        function DataCalculatorModuleButtonPushed(app, event)
            DataCalculatorModule(app, app.SamplesListBox.Value, {'VisModule'},app.UITable)

        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            %             InputTable=readtable('/Users/nils/Documents/PhD_Bern/Projects/Suzette_Jwaneng/Jwaneng_clinopyroxene_STimmerman_2025_NG.xlsx');
            %
            %             headers= InputTable.Properties.VariableNames;
            %             headers(ismember(headers,app.MassList)) = replace(headers(ismember(headers,app.MassList)),{'0','1','2','3','4','5','6','7','8','9','_mat_ugg'},'');
            %
            %             InputTable.Properties.VariableNames=matlab.lang.makeUniqueStrings(headers);
            %             app.Table=InputTable;
            checkedNodes = app.DiagramTree.CheckedNodes;
            for n=1:numel(checkedNodes)
                plotDiagram(app,checkedNodes(n))
            end

        end

        % Selection changed function: DiagramTree
        function DiagramTreeSelectionChanged(app, event)
            % Get the currently selected node
            selectedNode = event.SelectedNodes;
            if isempty(selectedNode)
                return;
            end
            cla(app.UIAxes);
            % Only display info if this is a diagram node
            if ~isempty(selectedNode.NodeData) && isstruct(selectedNode.NodeData) ...
                    && isfield(selectedNode.NodeData, 'Name') && ~isempty(selectedNode.NodeData.Name) && isfield(selectedNode.NodeData, 'X_Components') && ~isempty(~isempty({selectedNode.NodeData.X_Components}))

                diagramInfo = selectedNode.NodeData;

                % Build a concise info string
                infoStr = sprintf( ...
                    ['Category: %s / %s\n', ...
                    'Processes: %s\n', ...
                    '\nExpected Trends: %s\n', ...
                    '\nDetailed Description: %s\n', ...
                    'Tags: %s'], ...
                    diagramInfo.Category, diagramInfo.Subcategory, ...
                    diagramInfo.Processes, ...
                    diagramInfo.ExpectedTrends, ...
                    diagramInfo.DetailedDescription, ...
                    diagramInfo.Tags ...
                    );

                % Display concise info in TextArea
                app.TextArea.Value = infoStr;
                app.UIAxes.Title.String=diagramInfo.Title_TeX;
                app.UIAxes.XAxis.Label.String=diagramInfo.X_TeX;
                app.UIAxes.YAxis.Label.String=diagramInfo.Y_TeX;


                [Tout, axisNames] = prepare_axes_for_plot(app,app.UITable.Data, selectedNode.NodeData);
                if isfield(axisNames,'X')&& isfield(axisNames,'Y')&&not(isempty(axisNames.X))&&not(isempty(axisNames.Y))
                    [~,~,options] = plot_XY_data_fun([]);

                    options.InputTable=Tout;
                    options.X1 = axisNames.X;
                    options.Y1 = axisNames.Y;

                    options.which='custom_XY';
                    options.type.Value='custom_XY';
                    options.NewPlot=false;
                    options.SubDiv=app.UseDataSubdivisionCheckBox.Value;
                    options.custom.Interpreter='tex';%app.LabelInterpreterDropDown_2.Value;
                    options.ax2plot=app.UIAxes;

                    % XScale
                    if isfield(selectedNode.NodeData,'XScale') && ...
                            any(ismember(string(selectedNode.NodeData.XScale),["linear","log"]))
                        app.UIAxes.XAxis.Scale = selectedNode.NodeData.XScale;
                    else
                        app.UIAxes.XAxis.Scale = 'linear';
                    end

                    % YScale
                    if isfield(selectedNode.NodeData,'YScale') && ...
                            any(ismember(string(selectedNode.NodeData.YScale),["linear","log"]))
                        app.UIAxes.YAxis.Scale = selectedNode.NodeData.YScale;
                    else
                        app.UIAxes.YAxis.Scale = 'linear';
                    end

                    % XGrid
                    if isfield(selectedNode.NodeData,'XGrid') && ...
                            any(ismember(string(selectedNode.NodeData.XGrid),["on","off","true","false"]))
                        app.UIAxes.XGrid = selectedNode.NodeData.XGrid;
                    else
                        app.UIAxes.XGrid = 'off';
                    end

                    % YGrid
                    if isfield(selectedNode.NodeData,'YGrid') && ...
                            any(ismember(string(selectedNode.NodeData.YGrid),["on","off","true","false"]))
                        app.UIAxes.YGrid = selectedNode.NodeData.YGrid;
                    else
                        app.UIAxes.YGrid = 'off';
                    end

                    % XMinorGrid
                    if isfield(selectedNode.NodeData,'XMinorGrid') && ...
                            any(ismember(string(selectedNode.NodeData.XMinorGrid),["on","off","true","false"]))
                        app.UIAxes.XMinorGrid = selectedNode.NodeData.XMinorGrid;
                    else
                        app.UIAxes.XMinorGrid = 'off';
                    end

                    % YMinorGrid
                    if isfield(selectedNode.NodeData,'YMinorGrid') && ...
                            any(ismember(string(selectedNode.NodeData.YMinorGrid),["on","off","true","false"]))
                        app.UIAxes.YMinorGrid = selectedNode.NodeData.YMinorGrid;
                    else
                        app.UIAxes.YMinorGrid = 'off';
                    end


                    options.noprogressbar=true;

                    send_to_EasyPlot(app,options)

                    %[ax2plot,~,~] = plot_XY_data_fun(Tout, app.UIAxes, options);
                end
            else
                % Clear TextArea if not a diagram node
                app.TextArea.Value = '';
            end


            %             if app.XScaleDropDown.Visible==true
            %                 options.custom.XScale=app.XScaleDropDown.Value;
            %                 options.custom.YScale=app.YScaleDropDown.Value;
            %             end
            %
            %             if app.ZScaleDropDown.Visible==true
            %                 options.custom.ZScale=app.ZScaleDropDown.Value;
            %             end
            %
            %             if app.CScaleDropDown.Visible==true
            %                 options.custom.CScale=app.CScaleDropDown.Value;
            %             end


        end

        % Button pushed function: ReloadButton
        function ReloadButtonPushed(app, event)
            app.DiagramsDefinitionTable =  readtable('/Users/nils/Desktop/Book11.xlsx');
            update_diagram_tree(app,app.DiagramTree)
        end

        % Value changed function: DiagramTreeDropdown
        function DiagramTreeDropdownValueChanged(app, event)
      
            
            update_diagram_tree(app,app.DiagramTree)

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.VisModule_GUI = uifigure('Visible', 'off');
            app.VisModule_GUI.Name = 'VisModule';
            app.VisModule_GUI.CloseRequestFcn = createCallbackFcn(app, @VisModule_GUICloseRequest, true);
            app.GridLayout2 = uigridlayout(app.VisModule_GUI);
            app.TabGroup = uitabgroup(app.GridLayout2);
            app.PlotTab = uitab(app.TabGroup);
            app.PlotTab.Title = 'PlotTab';
            app.GridLayout3 = uigridlayout(app.PlotTab);
            app.Tree = uitree(app.GridLayout3, 'checkbox');
            app.Tree.Visible = 'off';
            app.FileInfoNode = uitreenode(app.Tree);
            app.FileInfoNode.Text = 'FileInfo';
            app.EPMA_Node = uitreenode(app.Tree);
            app.EPMA_Node.Text = 'EPMA';
            app.MSNode = uitreenode(app.Tree);
            app.MSNode.Text = 'MS';
            app.BulkNode = uitreenode(app.Tree);
            app.BulkNode.Text = 'Bulk';
            app.MetadataNode = uitreenode(app.Tree);
            app.MetadataNode.Text = 'Metadata';
            app.apfuNode = uitreenode(app.Tree);
            app.apfuNode.Text = 'apfu';
            app.StructuralFormularNode = uitreenode(app.Tree);
            app.StructuralFormularNode.Text = 'Structural Formular';
            app.CalcOutNode = uitreenode(app.Tree);
            app.CalcOutNode.Text = 'CalcOut';
            app.OptionsNode = uitreenode(app.Tree);
            app.OptionsNode.Text = 'Options';
            % Assign Checked Nodes
            app.Tree.CheckedNodes = [app.FileInfoNode, app.EPMA_Node, app.MetadataNode, app.apfuNode];
            % Assign Checked Nodes
            app.Tree.CheckedNodesChangedFcn = createCallbackFcn(app, @Tree_2CheckedNodesChanged, true);
            app.Tree_2 = uitree(app.GridLayout3, 'checkbox');
            % Assign Checked Nodes
            app.Tree_2.CheckedNodesChangedFcn = createCallbackFcn(app, @Tree_2CheckedNodesChanged, true);
            app.UpdateButton = uibutton(app.GridLayout3, 'push');
            app.UpdateButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateButtonPushed, true);
            app.UpdateButton.Text = 'Update';
            app.DropDown = uidropdown(app.GridLayout3);
            app.DropDown.Items = {'Sort By File', 'Sort By Mineral'};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @UpdateButtonPushed, true);
            app.DropDown.Value = 'Sort By File';
            app.ExportButton_2 = uibutton(app.GridLayout3, 'push');
            app.ExportButton_2.ButtonPushedFcn = createCallbackFcn(app, @ExportButton_2Pushed, true);
            app.ExportButton_2.Text = 'Export';
            app.TabGroup2 = uitabgroup(app.GridLayout3);
            app.ProfilePlotTab = uitab(app.TabGroup2);
            app.ProfilePlotTab.Title = 'Profile Plot';
            app.GridLayout5 = uigridlayout(app.ProfilePlotTab);
            app.Panel = uipanel(app.GridLayout5);
            app.ColorDropDownLabel = uilabel(app.GridLayout5);
            app.ColorDropDownLabel.Text = 'MarkerFaceColor';
            app.MarkerFaceColorDropDown = uidropdown(app.GridLayout5);
            app.MarkerFaceColorDropDown.Items = {'blue', 'orange', 'yellow', 'purple', 'green', 'cyan', 'red', 'black', 'none'};
            app.MarkerFaceColorDropDown.ItemsData = {'[0 0.4470 0.7410]', '[0.8500 0.3250 0.0980]', '[0.9290 0.6940 0.1250]', '[0.4940 0.1840 0.5560]', '[0.4660 0.6740 0.1880]', '[0.3010 0.7450 0.9330]', '[0.6350 0.0780 0.1840]', '[0 0 0]', 'none'};
            app.MarkerFaceColorDropDown.ValueChangedFcn = createCallbackFcn(app, @VarSelectionTreeCheckedNodesChanged, true);
            app.MarkerFaceColorDropDown.Value = '[0 0.4470 0.7410]';
            app.ColorDropDownLabel_2 = uilabel(app.GridLayout5);
            app.ColorDropDownLabel_2.Text = 'LineColor';
            app.LineColorDropDown = uidropdown(app.GridLayout5);
            app.LineColorDropDown.Items = {'blue', 'orange', 'yellow', 'purple', 'green', 'cyan', 'red', 'black'};
            app.LineColorDropDown.ItemsData = {'[0 0.4470 0.7410]', '[0.8500 0.3250 0.0980]', '[0.9290 0.6940 0.1250]', '[0.4940 0.1840 0.5560]', '[0.4660 0.6740 0.1880]', '[0.3010 0.7450 0.9330]', '[0.6350 0.0780 0.1840]', '[0 0 0]'};
            app.LineColorDropDown.ValueChangedFcn = createCallbackFcn(app, @VarSelectionTreeCheckedNodesChanged, true);
            app.LineColorDropDown.Value = '[0 0.4470 0.7410]';
            app.SymbolDropDownLabel = uilabel(app.GridLayout5);
            app.SymbolDropDownLabel.Text = 'MarkerSymbol';
            app.LineStyleDropDownLabel = uilabel(app.GridLayout5);
            app.LineStyleDropDownLabel.Text = 'LineStyle';
            app.LineStyleDropDown = uidropdown(app.GridLayout5);
            app.LineStyleDropDown.Items = {'No Line', 'Solid line', 'Dashed line', 'Dotted line', 'Dash-dotted line'};
            app.LineStyleDropDown.ItemsData = {'none ', '-', '--', ':', '-.'};
            app.LineStyleDropDown.ValueChangedFcn = createCallbackFcn(app, @VarSelectionTreeCheckedNodesChanged, true);
            app.LineStyleDropDown.Value = '-';
            app.SymbolDropDown = uidropdown(app.GridLayout5);
            app.SymbolDropDown.Items = {'Circle', 'Plus sign', 'Asterisk', 'Point', 'Cross', 'Horizontal line', 'Vertical line', 'Square', 'Diamond', 'Upward-pointing triangle', 'Downward-pointing triangle', 'Right-pointing triangle', 'Left-pointing triangle', 'Pentagram', 'Hexagram', 'none'};
            app.SymbolDropDown.ItemsData = {'o', '+', '*', '.', 'x', '_', '|', 's', 'd', '^', 'v', '>', '<', 'p', 'h', 'none'};
            app.SymbolDropDown.ValueChangedFcn = createCallbackFcn(app, @VarSelectionTreeCheckedNodesChanged, true);
            app.SymbolDropDown.Value = 'o';
            app.ColorDropDownLabel_3 = uilabel(app.GridLayout5);
            app.ColorDropDownLabel_3.Text = 'MarkerEdgeColor';
            app.MarkerEdgeColorDropDown = uidropdown(app.GridLayout5);
            app.MarkerEdgeColorDropDown.Items = {'blue', 'orange', 'yellow', 'purple', 'green', 'cyan', 'red', 'black', 'none'};
            app.MarkerEdgeColorDropDown.ItemsData = {'[0 0.4470 0.7410]', '[0.8500 0.3250 0.0980]', '[0.9290 0.6940 0.1250]', '[0.4940 0.1840 0.5560]', '[0.4660 0.6740 0.1880]', '[0.3010 0.7450 0.9330]', '[0.6350 0.0780 0.1840]', '[0 0 0]', 'none'};
            app.MarkerEdgeColorDropDown.ValueChangedFcn = createCallbackFcn(app, @VarSelectionTreeCheckedNodesChanged, true);
            app.MarkerEdgeColorDropDown.Value = '[0 0.4470 0.7410]';
            app.MarkerSizeSpinnerLabel = uilabel(app.GridLayout5);
            app.MarkerSizeSpinnerLabel.Text = 'MarkerSize';
            app.MarkerSizeSpinner = uispinner(app.GridLayout5);
            app.MarkerSizeSpinner.Limits = [1 1000];
            app.MarkerSizeSpinner.ValueChangedFcn = createCallbackFcn(app, @VarSelectionTreeCheckedNodesChanged, true);
            app.MarkerSizeSpinner.Value = 10;
            app.LineWidthSpinnerLabel = uilabel(app.GridLayout5);
            app.LineWidthSpinnerLabel.Text = 'LineWidth';
            app.LineWidthSpinner = uispinner(app.GridLayout5);
            app.LineWidthSpinner.Limits = [1 100];
            app.LineWidthSpinner.ValueChangedFcn = createCallbackFcn(app, @VarSelectionTreeCheckedNodesChanged, true);
            app.LineWidthSpinner.Value = 1;
            app.NumberofColumnsSpinnerLabel = uilabel(app.GridLayout5);
            app.NumberofColumnsSpinnerLabel.Text = 'Number of Columns';
            app.NumberofColumnsSpinner = uispinner(app.GridLayout5);
            app.NumberofColumnsSpinner.Limits = [1 20];
            app.NumberofColumnsSpinner.ValueChangedFcn = createCallbackFcn(app, @VarSelectionTreeCheckedNodesChanged, true);
            app.NumberofColumnsSpinner.Value = 2;
            app.RowHightLabel = uilabel(app.GridLayout5);
            app.RowHightLabel.Visible = 'off';
            app.RowHightLabel.Text = 'RowHight';
            app.RowHightSpinner = uispinner(app.GridLayout5);
            app.RowHightSpinner.Step = 10;
            app.RowHightSpinner.Limits = [1 20000];
            app.RowHightSpinner.ValueChangedFcn = createCallbackFcn(app, @VarSelectionTreeCheckedNodesChanged, true);
            app.RowHightSpinner.Visible = 'off';
            app.RowHightSpinner.Value = 200;
            app.LayoutDropDownLabel = uilabel(app.GridLayout5);
            app.LayoutDropDownLabel.Text = 'Layout';
            app.LayoutDropDown = uidropdown(app.GridLayout5);
            app.LayoutDropDown.Items = {'Stacked', 'Tiled'};
            app.LayoutDropDown.DropDownOpeningFcn = createCallbackFcn(app, @LayoutDropDownOpening, true);
            app.LayoutDropDown.ValueChangedFcn = createCallbackFcn(app, @VarSelectionTreeCheckedNodesChanged, true);
            app.LayoutDropDown.Value = 'Stacked';
            app.HistogramFilterButton = uibutton(app.GridLayout5, 'push');
            app.HistogramFilterButton.ButtonPushedFcn = createCallbackFcn(app, @HistHistogramFilterButtonPushed, true);
            app.HistogramFilterButton.Text = 'Histogram Filter';
            app.DisplayFilteredCheckBox = uicheckbox(app.GridLayout5);
            app.DisplayFilteredCheckBox.ValueChangedFcn = createCallbackFcn(app, @VarSelectionTreeCheckedNodesChanged, true);
            app.DisplayFilteredCheckBox.Text = 'Display Filtered';
            app.XAxesVariableDropDownLabel = uilabel(app.GridLayout5);
            app.XAxesVariableDropDownLabel.Text = 'X Axes Variable';
            app.XAxesVariableDropDown = uidropdown(app.GridLayout5);
            app.XAxesVariableDropDown.Items = {'ProfileDistance', '1:n'};
            app.XAxesVariableDropDown.DropDownOpeningFcn = createCallbackFcn(app, @XAxesVariableDropDownOpening, true);
            app.XAxesVariableDropDown.ValueChangedFcn = createCallbackFcn(app, @XAxesVariableDropDownValueChanged, true);
            app.XAxesVariableDropDown.Value = '1:n';
            app.FilterasNaNCheckBox = uicheckbox(app.GridLayout5);
            app.FilterasNaNCheckBox.ValueChangedFcn = createCallbackFcn(app, @VarSelectionTreeCheckedNodesChanged, true);
            app.FilterasNaNCheckBox.Text = 'Filter as NaN';
            app.FigureFontSizeSpinnerLabel = uilabel(app.GridLayout5);
            app.FigureFontSizeSpinnerLabel.Text = 'Figure FontSize';
            app.FigureFontSizeSpinner = uispinner(app.GridLayout5);
            app.FigureFontSizeSpinner.ValueChangingFcn = createCallbackFcn(app, @VarSelectionTreeCheckedNodesChanged, true);
            app.FigureFontSizeSpinner.Limits = [1 Inf];
            app.FigureFontSizeSpinner.ValueChangedFcn = createCallbackFcn(app, @FigureFontSizeSpinnerValueChanged, true);
            app.FigureFontSizeSpinner.Value = 14;
            app.VarSelectionTree = uitree(app.GridLayout5, 'checkbox');
            % Assign Checked Nodes
            app.VarSelectionTree.CheckedNodesChangedFcn = createCallbackFcn(app, @VarSelectionTreeCheckedNodesChanged, true);
            app.NewEasyPlotButton = uibutton(app.GridLayout5, 'push');
            app.NewEasyPlotButton.ButtonPushedFcn = createCallbackFcn(app, @NewEasyPlotButtonPushed, true);
            app.NewEasyPlotButton.Text = 'New Profile EasyPlot';
            app.AddToEasyPlotButton = uibutton(app.GridLayout5, 'push');
            app.AddToEasyPlotButton.ButtonPushedFcn = createCallbackFcn(app, @AddToEasyPlotButtonPushed, true);
            app.AddToEasyPlotButton.Text = 'Add To Profile EasyPlot';
            app.IndividualEasyPlotButton = uibutton(app.GridLayout5, 'push');
            app.IndividualEasyPlotButton.ButtonPushedFcn = createCallbackFcn(app, @IndividualEasyPlotButtonPushed, true);
            app.IndividualEasyPlotButton.Visible = 'off';
            app.IndividualEasyPlotButton.Text = 'Individual Profile EasyPlot';
            app.CustomAxesLabelsCheckBox = uicheckbox(app.GridLayout5);
            app.CustomAxesLabelsCheckBox.ValueChangedFcn = createCallbackFcn(app, @CustomAxesLabelsCheckBoxValueChanged, true);
            app.CustomAxesLabelsCheckBox.Text = 'Custom Axes Labels';
            app.LabelInterpreterDropDownLabel = uilabel(app.GridLayout5);
            app.LabelInterpreterDropDownLabel.Text = 'Label Interpreter';
            app.LabelInterpreterDropDown = uidropdown(app.GridLayout5);
            app.LabelInterpreterDropDown.Items = {'none', 'tex', 'latex'};
            app.LabelInterpreterDropDown.Value = 'none';
            app.CustomXLabelEditFieldLabel = uilabel(app.GridLayout5);
            app.CustomXLabelEditFieldLabel.Visible = 'off';
            app.CustomXLabelEditFieldLabel.Text = 'Custom XLabel';
            app.CustomXLabelEditField = uieditfield(app.GridLayout5, 'text');
            app.CustomXLabelEditField.Visible = 'off';
            app.CustomYLabelEditFieldLabel = uilabel(app.GridLayout5);
            app.CustomYLabelEditFieldLabel.Visible = 'off';
            app.CustomYLabelEditFieldLabel.Text = 'Custom YLabel';
            app.CustomYLabelEditField = uieditfield(app.GridLayout5, 'text');
            app.CustomYLabelEditField.Visible = 'off';
            app.DataSelectionTab = uitab(app.TabGroup2);
            app.DataSelectionTab.Title = 'Data Selection';
            app.GridLayout4 = uigridlayout(app.DataSelectionTab);
            app.ClassTable = uitable(app.GridLayout4);
            app.ClassTable.ColumnName = {'Active'; 'Name'; 'Value'; 'n'};
            app.ClassTable.RowName = {};
            app.ClassTable.ColumnSortable = true;
            app.MethodDropDownLabel = uilabel(app.GridLayout4);
            app.MethodDropDownLabel.Visible = 'off';
            app.MethodDropDownLabel.Text = 'Method';
            app.MethodDropDown = uidropdown(app.GridLayout4);
            app.MethodDropDown.Items = {'Unique String'};
            app.MethodDropDown.Visible = 'off';
            app.MethodDropDown.Value = 'Unique String';
            app.VariableDropDown = uidropdown(app.GridLayout4);
            app.VariableDropDown.DropDownOpeningFcn = createCallbackFcn(app, @VariableDropDownOpening, true);
            app.VariableDropDown.ValueChangedFcn = createCallbackFcn(app, @VariableDropDownValueChanged, true);
            app.MaskSelectionButton = uibutton(app.GridLayout4, 'push');
            app.MaskSelectionButton.ButtonPushedFcn = createCallbackFcn(app, @MaskSelectionButtonPushed, true);
            app.MaskSelectionButton.Text = ' Mask Selection';
            app.ResetMaskButton = uibutton(app.GridLayout4, 'push');
            app.ResetMaskButton.ButtonPushedFcn = createCallbackFcn(app, @ResetMaskButtonPushed, true);
            app.ResetMaskButton.Text = 'Reset Mask';
            app.SelectAllButton = uibutton(app.GridLayout4, 'push');
            app.SelectAllButton.ButtonPushedFcn = createCallbackFcn(app, @SelectAllButtonPushed, true);
            app.SelectAllButton.Text = 'Select All';
            app.UnmaskButton = uibutton(app.GridLayout4, 'push');
            app.UnmaskButton.ButtonPushedFcn = createCallbackFcn(app, @UnmaskButtonPushed, true);
            app.UnmaskButton.Text = 'Unmask';
            app.ExcludeEmptyCheckBox = uicheckbox(app.GridLayout4);
            app.ExcludeEmptyCheckBox.ValueChangedFcn = createCallbackFcn(app, @ExcludeEmptyCheckBoxValueChanged, true);
            app.ExcludeEmptyCheckBox.Text = 'Exclude Empty';
            app.HistogramFilterButton_2 = uibutton(app.GridLayout4, 'push');
            app.HistogramFilterButton_2.ButtonPushedFcn = createCallbackFcn(app, @HistHistogramFilterButtonPushed, true);
            app.HistogramFilterButton_2.Text = 'HistogramFilter';
            app.DataSubdivisionTab = uitab(app.TabGroup2);
            app.DataSubdivisionTab.Title = 'Data Subdivision';
            app.GridLayout6 = uigridlayout(app.DataSubdivisionTab);
            app.ResetButton = uibutton(app.GridLayout6, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Visible = 'off';
            app.ResetButton.Text = 'Reset';
            app.UseDataSubdivisionCheckBox = uicheckbox(app.GridLayout6);
            app.UseDataSubdivisionCheckBox.ValueChangedFcn = createCallbackFcn(app, @UseDataSubdivisionCheckBoxValueChanged, true);
            app.UseDataSubdivisionCheckBox.Text = 'Use Data Subdivision';
            app.ListBox = uilistbox(app.GridLayout6);
            app.ListBox.Items = {};
            app.ListBox.ValueChangedFcn = createCallbackFcn(app, @ListBoxValueChanged, true);
            app.ListBox.Visible = 'off';
            app.ListBox.Value = {};
            app.ListBox_2 = uilistbox(app.GridLayout6);
            app.ListBox_2.Items = {};
            app.ListBox_2.Multiselect = 'on';
            app.ListBox_2.ValueChangedFcn = createCallbackFcn(app, @ListBox_2ValueChanged, true);
            app.ListBox_2.Visible = 'off';
            app.ListBox_2.Value = {};
            app.nEditFieldLabel = uilabel(app.GridLayout6);
            app.nEditFieldLabel.Visible = 'off';
            app.nEditFieldLabel.Text = 'n';
            app.nEditField = uieditfield(app.GridLayout6, 'numeric');
            app.nEditField.Editable = 'off';
            app.nEditField.Visible = 'off';
            app.AppearanceDropDownLabel = uilabel(app.GridLayout6);
            app.AppearanceDropDownLabel.Text = 'Appearance';
            app.AppearanceDropDown = uidropdown(app.GridLayout6);
            app.AppearanceDropDown.Items = {'Color Symbol', 'Symbol Color'};
            app.AppearanceDropDown.Value = 'Color Symbol';
            app.GeoCPlotButton = uibutton(app.GridLayout6, 'push');
            app.GeoCPlotButton.ButtonPushedFcn = createCallbackFcn(app, @GeoCPlotButtonPushed, true);
            app.GeoCPlotButton.Text = 'GeoCPlot';
            app.SplitMatrixInclusionButton = uibutton(app.GridLayout6, 'push');
            app.SplitMatrixInclusionButton.ButtonPushedFcn = createCallbackFcn(app, @SplitMatrixInclusionButtonPushed, true);
            app.SplitMatrixInclusionButton.Visible = 'off';
            app.SplitMatrixInclusionButton.Text = 'Split Matrix / Inclusion';
            app.CombineColumnsButton = uibutton(app.GridLayout6, 'push');
            app.CombineColumnsButton.ButtonPushedFcn = createCallbackFcn(app, @CombineColumnsButtonPushed, true);
            app.CombineColumnsButton.Text = 'Combine Columns';
            app.CustomPlotsTab = uitab(app.TabGroup2);
            app.CustomPlotsTab.Title = 'Custom Plots';
            app.GridLayout8 = uigridlayout(app.CustomPlotsTab);
            app.NewXYEasyPlotButton_4 = uibutton(app.GridLayout8, 'push');
            app.NewXYEasyPlotButton_4.ButtonPushedFcn = createCallbackFcn(app, @NewXYEasyPlotButton_4Pushed, true);
            app.NewXYEasyPlotButton_4.Text = 'New XY EasyPlot';
            app.Panel_2 = uipanel(app.GridLayout8);
            app.Panel_2.Visible = 'off';
            app.GridLayout = uigridlayout(app.Panel_2);
            app.DisplayValuesOutsideData = uicheckbox(app.GridLayout);
            app.DisplayValuesOutsideData.Text = 'Display Values Outside Data Range (No C Limit Update)';
            app.Options_LowerCheckBox = uicheckbox(app.GridLayout);
            app.Options_LowerCheckBox.Tooltip = {'Add a lower color layer'};
            app.Options_LowerCheckBox.Text = 'Lower';
            app.Options_LowerCheckBox.Value = true;
            app.Options_UpperCheckBox = uicheckbox(app.GridLayout);
            app.Options_UpperCheckBox.Tooltip = {'Add an upper color layer'};
            app.Options_UpperCheckBox.Text = 'Upper';
            app.Options_UpperColor = uidropdown(app.GridLayout);
            app.Options_UpperColor.Items = {'Black', 'White'};
            app.Options_UpperColor.Tooltip = {'Select the color for the upper layer'};
            app.Options_UpperColor.Value = 'Black';
            app.Options_ColormapResEditField = uieditfield(app.GridLayout, 'numeric');
            app.Options_ColormapResEditField.Limits = [3 9999];
            app.Options_ColormapResEditField.RoundFractionalValues = 'on';
            app.Options_ColormapResEditField.Tooltip = {'Colormap resolution (def. 256)'};
            app.Options_ColormapResEditField.Value = 256;
            app.Options_LogColormap = uicheckbox(app.GridLayout);
            app.Options_LogColormap.Tooltip = {'Use a log colormap'};
            app.Options_LogColormap.Text = 'Log';
            app.ColormapDropDownLabel = uilabel(app.GridLayout);
            app.ColormapDropDownLabel.Text = 'Colormap';
            app.Options_ColormapDropDown = uidropdown(app.GridLayout);
            app.Options_ColormapDropDown.Tooltip = {'Select the active colormap'};
            app.Options_InvertColormap = uicheckbox(app.GridLayout);
            app.Options_InvertColormap.Text = 'Invert';
            app.Options_LowerColor = uidropdown(app.GridLayout);
            app.Options_LowerColor.Items = {'Black', 'White'};
            app.Options_LowerColor.Tooltip = {'Select the color for the lower layer'};
            app.Options_LowerColor.Value = 'Black';
            app.CustomColormapCheckBox = uicheckbox(app.GridLayout8);
            app.CustomColormapCheckBox.ValueChangedFcn = createCallbackFcn(app, @CustomColormapCheckBoxValueChanged, true);
            app.CustomColormapCheckBox.Text = 'Custom Colormap';
            app.IndividualXYEasyPlotButton = uibutton(app.GridLayout8, 'push');
            app.IndividualXYEasyPlotButton.ButtonPushedFcn = createCallbackFcn(app, @IndividualXYEasyPlotButtonPushed, true);
            app.IndividualXYEasyPlotButton.Visible = 'off';
            app.IndividualXYEasyPlotButton.Text = 'Individual XY EasyPlot';
            app.XAxesVariableDropDown_2Label = uilabel(app.GridLayout8);
            app.XAxesVariableDropDown_2Label.Text = 'X Axes Variable';
            app.XAxesVariableDropDown_2 = uidropdown(app.GridLayout8);
            app.XAxesVariableDropDown_2.Items = {'ProfileDistance', '1:n'};
            app.XAxesVariableDropDown_2.DropDownOpeningFcn = createCallbackFcn(app, @XAxesVariableDropDown_2Opening, true);
            app.XAxesVariableDropDown_2.ValueChangedFcn = createCallbackFcn(app, @XAxesVariableDropDown_2ValueChanged, true);
            app.XAxesVariableDropDown_2.Value = '1:n';
            app.YAxesVariableDropDownLabel = uilabel(app.GridLayout8);
            app.YAxesVariableDropDownLabel.Text = 'Y Axes Variable';
            app.YAxesVariableDropDown = uidropdown(app.GridLayout8);
            app.YAxesVariableDropDown.Items = {'ProfileDistance', '1:n'};
            app.YAxesVariableDropDown.DropDownOpeningFcn = createCallbackFcn(app, @YAxesVariableDropDownOpening, true);
            app.YAxesVariableDropDown.ValueChangedFcn = createCallbackFcn(app, @YAxesVariableDropDownValueChanged, true);
            app.YAxesVariableDropDown.Value = '1:n';
            app.ZAxesVariableDropDownLabel = uilabel(app.GridLayout8);
            app.ZAxesVariableDropDownLabel.Visible = 'off';
            app.ZAxesVariableDropDownLabel.Text = 'Z Axes Variable';
            app.ZAxesVariableDropDown = uidropdown(app.GridLayout8);
            app.ZAxesVariableDropDown.Items = {'ProfileDistance', '1:n'};
            app.ZAxesVariableDropDown.DropDownOpeningFcn = createCallbackFcn(app, @ZAxesVariableDropDownOpening, true);
            app.ZAxesVariableDropDown.ValueChangedFcn = createCallbackFcn(app, @ZAxesVariableDropDownValueChanged, true);
            app.ZAxesVariableDropDown.Visible = 'off';
            app.ZAxesVariableDropDown.Value = '1:n';
            app.CAxesVariableDropDownLabel = uilabel(app.GridLayout8);
            app.CAxesVariableDropDownLabel.Visible = 'off';
            app.CAxesVariableDropDownLabel.Text = 'C Axes Variable';
            app.CAxesVariableDropDown = uidropdown(app.GridLayout8);
            app.CAxesVariableDropDown.Items = {'1'};
            app.CAxesVariableDropDown.DropDownOpeningFcn = createCallbackFcn(app, @CAxesVariableDropDownOpening, true);
            app.CAxesVariableDropDown.ValueChangedFcn = createCallbackFcn(app, @CAxesVariableDropDownValueChanged, true);
            app.CAxesVariableDropDown.Visible = 'off';
            app.CAxesVariableDropDown.Value = '1';
            app.XLabelEditFieldLabel = uilabel(app.GridLayout8);
            app.XLabelEditFieldLabel.Text = 'XLabel';
            app.XLabelEditField = uieditfield(app.GridLayout8, 'text');
            app.YLabelEditFieldLabel = uilabel(app.GridLayout8);
            app.YLabelEditFieldLabel.Text = 'YLabel';
            app.YLabelEditField = uieditfield(app.GridLayout8, 'text');
            app.ZLabelEditFieldLabel = uilabel(app.GridLayout8);
            app.ZLabelEditFieldLabel.Visible = 'off';
            app.ZLabelEditFieldLabel.Text = 'ZLabel';
            app.ZLabelEditField = uieditfield(app.GridLayout8, 'text');
            app.ZLabelEditField.Visible = 'off';
            app.ColorbarLabelEditFieldLabel = uilabel(app.GridLayout8);
            app.ColorbarLabelEditFieldLabel.Visible = 'off';
            app.ColorbarLabelEditFieldLabel.Text = 'Colorbar Label';
            app.ColorbarLabelEditField = uieditfield(app.GridLayout8, 'text');
            app.ColorbarLabelEditField.Visible = 'off';
            app.PlotTypeLabel = uilabel(app.GridLayout8);
            app.PlotTypeLabel.Text = 'PlotType';
            app.PlotType_DropDown = uidropdown(app.GridLayout8);
            app.PlotType_DropDown.Items = {'XY', 'XYZ', 'Ternary', 'Tetrahedron', 'XY Ratio A/B vs C/D', 'XYZ Ratio A/B vs C/D vs E/F'};
            app.PlotType_DropDown.ValueChangedFcn = createCallbackFcn(app, @PlotType_DropDownValueChanged, true);
            app.PlotType_DropDown.Value = 'XY';
            app.DLabelEditFieldLabel = uilabel(app.GridLayout8);
            app.DLabelEditFieldLabel.Visible = 'off';
            app.DLabelEditFieldLabel.Text = 'DLabel';
            app.DLabelEditField = uieditfield(app.GridLayout8, 'text');
            app.DLabelEditField.Visible = 'off';
            app.DAxesVariableDropDownLabel = uilabel(app.GridLayout8);
            app.DAxesVariableDropDownLabel.Visible = 'off';
            app.DAxesVariableDropDownLabel.Text = 'D Axes Variable';
            app.DAxesVariableDropDown = uidropdown(app.GridLayout8);
            app.DAxesVariableDropDown.Items = {'ProfileDistance', '1:n'};
            app.DAxesVariableDropDown.DropDownOpeningFcn = createCallbackFcn(app, @DAxesVariableDropDownOpening, true);
            app.DAxesVariableDropDown.ValueChangedFcn = createCallbackFcn(app, @DAxesVariableDropDownValueChanged, true);
            app.DAxesVariableDropDown.Visible = 'off';
            app.DAxesVariableDropDown.Value = '1:n';
            app.LabelInterpreterDropDown_2Label = uilabel(app.GridLayout8);
            app.LabelInterpreterDropDown_2Label.Text = 'Label Interpreter';
            app.LabelInterpreterDropDown_2 = uidropdown(app.GridLayout8);
            app.LabelInterpreterDropDown_2.Items = {'none', 'tex', 'latex'};
            app.LabelInterpreterDropDown_2.Value = 'none';
            app.FigureFontSizeSpinner_3Label = uilabel(app.GridLayout8);
            app.FigureFontSizeSpinner_3Label.Text = 'Figure FontSize';
            app.FigureFontSizeSpinner_3 = uispinner(app.GridLayout8);
            app.FigureFontSizeSpinner_3.Limits = [1 Inf];
            app.FigureFontSizeSpinner_3.ValueChangedFcn = createCallbackFcn(app, @FigureFontSizeSpinnerValueChanged, true);
            app.FigureFontSizeSpinner_3.Value = 14;
            app.XScaleDropDownLabel = uilabel(app.GridLayout8);
            app.XScaleDropDownLabel.Text = 'X Scale';
            app.XScaleDropDown = uidropdown(app.GridLayout8);
            app.XScaleDropDown.Items = {'linear', 'log'};
            app.XScaleDropDown.ValueChangedFcn = createCallbackFcn(app, @XYZCScaleDropDownValueChanged, true);
            app.XScaleDropDown.Value = 'linear';
            app.YScaleDropDown = uidropdown(app.GridLayout8);
            app.YScaleDropDown.Items = {'linear', 'log'};
            app.YScaleDropDown.ValueChangedFcn = createCallbackFcn(app, @XYZCScaleDropDownValueChanged, true);
            app.YScaleDropDown.Value = 'linear';
            app.YScaleDropDownLabel = uilabel(app.GridLayout8);
            app.YScaleDropDownLabel.Text = 'Y Scale';
            app.ZScaleDropDownLabel = uilabel(app.GridLayout8);
            app.ZScaleDropDownLabel.Visible = 'off';
            app.ZScaleDropDownLabel.Text = 'Z Scale';
            app.ZScaleDropDown = uidropdown(app.GridLayout8);
            app.ZScaleDropDown.Items = {'linear', 'log'};
            app.ZScaleDropDown.ValueChangedFcn = createCallbackFcn(app, @XYZCScaleDropDownValueChanged, true);
            app.ZScaleDropDown.Visible = 'off';
            app.ZScaleDropDown.Value = 'linear';
            app.CScaleDropDownLabel = uilabel(app.GridLayout8);
            app.CScaleDropDownLabel.Visible = 'off';
            app.CScaleDropDownLabel.Text = 'C Scale';
            app.CScaleDropDown = uidropdown(app.GridLayout8);
            app.CScaleDropDown.Items = {'linear', 'log'};
            app.CScaleDropDown.ValueChangedFcn = createCallbackFcn(app, @XYZCScaleDropDownValueChanged, true);
            app.CScaleDropDown.Visible = 'off';
            app.CScaleDropDown.Value = 'linear';
            app.AnnotationCheckBox = uicheckbox(app.GridLayout8);
            app.AnnotationCheckBox.ValueChangedFcn = createCallbackFcn(app, @AnnotationCheckBoxValueChanged, true);
            app.AnnotationCheckBox.Text = 'Annotation';
            app.AnnotationListBox = uilistbox(app.GridLayout8);
            app.AnnotationListBox.Items = {};
            app.AnnotationListBox.Multiselect = 'on';
            app.AnnotationListBox.ValueChangedFcn = createCallbackFcn(app, @AnnotationListBox_ValueChanged, true);
            app.AnnotationListBox.Visible = 'off';
            app.AnnotationListBox.Value = {};
            app.ScaleDropDownLabel = uilabel(app.GridLayout8);
            app.ScaleDropDownLabel.Text = 'Scale';
            app.ScaleDropDown = uidropdown(app.GridLayout8);
            app.ScaleDropDown.Items = {'All Linear', 'All Log', 'Custom'};
            app.ScaleDropDown.ValueChangedFcn = createCallbackFcn(app, @ScaleDropDownValueChanged, true);
            app.ScaleDropDown.Value = 'All Linear';
            app.EAxesVariableDropDownLabel = uilabel(app.GridLayout8);
            app.EAxesVariableDropDownLabel.Visible = 'off';
            app.EAxesVariableDropDownLabel.Text = 'E Axes Variable';
            app.EAxesVariableDropDown = uidropdown(app.GridLayout8);
            app.EAxesVariableDropDown.Items = {'ProfileDistance', '1:n'};
            app.EAxesVariableDropDown.DropDownOpeningFcn = createCallbackFcn(app, @EAxesVariableDropDownOpening, true);
            app.EAxesVariableDropDown.ValueChangedFcn = createCallbackFcn(app, @EAxesVariableDropDownValueChanged, true);
            app.EAxesVariableDropDown.Visible = 'off';
            app.EAxesVariableDropDown.Value = '1:n';
            app.FAxesVariableDropDownLabel = uilabel(app.GridLayout8);
            app.FAxesVariableDropDownLabel.Visible = 'off';
            app.FAxesVariableDropDownLabel.Text = 'F Axes Variable';
            app.FAxesVariableDropDown = uidropdown(app.GridLayout8);
            app.FAxesVariableDropDown.Items = {'1'};
            app.FAxesVariableDropDown.DropDownOpeningFcn = createCallbackFcn(app, @FAxesVariableDropDownOpening, true);
            app.FAxesVariableDropDown.ValueChangedFcn = createCallbackFcn(app, @FAxesVariableDropDownValueChanged, true);
            app.FAxesVariableDropDown.Visible = 'off';
            app.FAxesVariableDropDown.Value = '1';
            app.ELabelEditFieldLabel = uilabel(app.GridLayout8);
            app.ELabelEditFieldLabel.Visible = 'off';
            app.ELabelEditFieldLabel.Text = 'ELabel';
            app.ELabelEditField = uieditfield(app.GridLayout8, 'text');
            app.ELabelEditField.Visible = 'off';
            app.FLabelEditFieldLabel = uilabel(app.GridLayout8);
            app.FLabelEditFieldLabel.Visible = 'off';
            app.FLabelEditFieldLabel.Text = 'FLabel';
            app.FLabelEditField = uieditfield(app.GridLayout8, 'text');
            app.FLabelEditField.Visible = 'off';
            app.TraceElementsTab = uitab(app.TabGroup2);
            app.TraceElementsTab.Title = 'TraceElements';
            app.GridLayout14 = uigridlayout(app.TraceElementsTab);
            app.SettingsPanel = uipanel(app.GridLayout14);
            app.SettingsPanel.Title = 'Settings';
            app.GridLayout13 = uigridlayout(app.SettingsPanel);
            app.AnnotationCheckBox_2 = uicheckbox(app.GridLayout13);
            app.AnnotationCheckBox_2.ValueChangedFcn = createCallbackFcn(app, @AnnotationCheckBoxValueChanged, true);
            app.AnnotationCheckBox_2.Text = 'Annotation';
            app.AnnotationListBox_TE = uilistbox(app.GridLayout13);
            app.AnnotationListBox_TE.Items = {};
            app.AnnotationListBox_TE.Multiselect = 'on';
            app.AnnotationListBox_TE.ValueChangedFcn = createCallbackFcn(app, @AnnotationListBox_ValueChanged, true);
            app.AnnotationListBox_TE.Visible = 'off';
            app.AnnotationListBox_TE.Value = {};
            app.FilterElementsCheckBox = uicheckbox(app.GridLayout13);
            app.FilterElementsCheckBox.ValueChangedFcn = createCallbackFcn(app, @FilterElementsCheckBoxValueChanged, true);
            app.FilterElementsCheckBox.Text = 'Filter  Elements';
            app.FigureFontSizeSpinner_4Label = uilabel(app.GridLayout13);
            app.FigureFontSizeSpinner_4Label.Text = 'Figure FontSize';
            app.FigureFontSizeSpinner_4 = uispinner(app.GridLayout13);
            app.FigureFontSizeSpinner_4.Limits = [1 Inf];
            app.FigureFontSizeSpinner_4.ValueChangedFcn = createCallbackFcn(app, @FigureFontSizeSpinnerValueChanged, true);
            app.FigureFontSizeSpinner_4.Value = 14;
            app.NormalisedDiagramsPanel = uipanel(app.GridLayout14);
            app.NormalisedDiagramsPanel.Title = 'Normalised Diagrams';
            app.GridLayout12 = uigridlayout(app.NormalisedDiagramsPanel);
            app.NormalisedTEPlotButton = uibutton(app.GridLayout12, 'push');
            app.NormalisedTEPlotButton.ButtonPushedFcn = createCallbackFcn(app, @NormalisedTEPlotButtonPushed, true);
            app.NormalisedTEPlotButton.Text = 'Normalised TE Plot';
            app.UpdateNormListButton = uibutton(app.GridLayout12, 'push');
            app.UpdateNormListButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateNormListButtonPushed, true);
            app.UpdateNormListButton.Text = 'Update Norm List';
            app.NormalisetoDropDownLabel = uilabel(app.GridLayout12);
            app.NormalisetoDropDownLabel.Text = 'Normalise to';
            app.NormalisetoDropDown = uidropdown(app.GridLayout12);
            app.NormalisetoDropDown.Items = {};
            app.NormalisetoDropDown.Value = {};
            app.UserNormPanel = uipanel(app.GridLayout14);
            app.UserNormPanel.Title = 'User Norm';
            app.GridLayout10 = uigridlayout(app.UserNormPanel);
            app.ListBox_3 = uilistbox(app.GridLayout10);
            app.ListBox_3.Items = {};
            app.ListBox_3.ValueChangedFcn = createCallbackFcn(app, @ListBox_3ValueChanged, true);
            app.ListBox_3.Visible = 'off';
            app.ListBox_3.Value = {};
            app.ListBox_4 = uilistbox(app.GridLayout10);
            app.ListBox_4.Items = {};
            app.ListBox_4.ValueChangedFcn = createCallbackFcn(app, @ListBox_4ValueChanged, true);
            app.ListBox_4.Visible = 'off';
            app.ListBox_4.Value = {};
            app.SaveNormDataButton = uibutton(app.GridLayout10, 'push');
            app.SaveNormDataButton.ButtonPushedFcn = createCallbackFcn(app, @SaveNormDataButtonPushed, true);
            app.SaveNormDataButton.Text = 'Save Norm Data';
            app.UserNormTraceElementPlotButton = uibutton(app.GridLayout10, 'push');
            app.UserNormTraceElementPlotButton.ButtonPushedFcn = createCallbackFcn(app, @UserNormTraceElementPlotButtonPushed, true);
            app.UserNormTraceElementPlotButton.Visible = 'off';
            app.UserNormTraceElementPlotButton.Text = 'User Norm Trace Element Plot';
            app.SigDigitsSpinnerLabel = uilabel(app.GridLayout10);
            app.SigDigitsSpinnerLabel.Text = 'Sig. Digits';
            app.SigDigitsSpinner = uispinner(app.GridLayout10);
            app.SigDigitsSpinner.Limits = [0 16];
            app.SigDigitsSpinner.RoundFractionalValues = 'on';
            app.SigDigitsSpinner.Value = 3;
            app.UserNormCheckBox = uicheckbox(app.GridLayout10);
            app.UserNormCheckBox.ValueChangedFcn = createCallbackFcn(app, @UserNormCheckBoxValueChanged, true);
            app.UserNormCheckBox.Text = 'UserNorm';
            app.UserNormCheckBox.Value = true;
            app.TraceElementPlotPanel = uipanel(app.GridLayout14);
            app.TraceElementPlotPanel.Title = 'Trace Element Plot';
            app.GridLayout11 = uigridlayout(app.TraceElementPlotPanel);
            app.ElementstoPlotListBox = uilistbox(app.GridLayout11);
            app.ElementstoPlotListBox.Items = {};
            app.ElementstoPlotListBox.Multiselect = 'on';
            app.ElementstoPlotListBox.Visible = 'off';
            app.ElementstoPlotListBox.Value = {};
            app.TraceElementPlotButton = uibutton(app.GridLayout11, 'push');
            app.TraceElementPlotButton.ButtonPushedFcn = createCallbackFcn(app, @TraceElementPlotButtonPushed, true);
            app.TraceElementPlotButton.Visible = 'off';
            app.TraceElementPlotButton.Text = 'Trace Element Plot';
            app.DefaultsDropDownLabel = uilabel(app.GridLayout11);
            app.DefaultsDropDownLabel.Visible = 'off';
            app.DefaultsDropDownLabel.Text = 'Defaults';
            app.DefaultsDropDown = uidropdown(app.GridLayout11);
            app.DefaultsDropDown.Items = {'Element data in table', 'All Elements', 'REE (Ionic Radii)', 'REE', 'Trace Elements', 'Spider', 'Elements in table', 'Masses in table', 'Mass data in table', 'Mass data in table ~0'};
            app.DefaultsDropDown.ValueChangedFcn = createCallbackFcn(app, @DefaultsDropDownValueChanged, true);
            app.DefaultsDropDown.Visible = 'off';
            app.DefaultsDropDown.Value = 'Element data in table';
            app.PlotDetectionLimitCheckBox = uicheckbox(app.GridLayout11);
            app.PlotDetectionLimitCheckBox.Visible = 'off';
            app.PlotDetectionLimitCheckBox.Text = 'Plot Detection Limit';
            app.DropDown_2 = uidropdown(app.GridLayout11);
            app.DropDown_2.Items = {'All', 'Maximum', 'Minimum', 'Range', 'Mean', 'Median', 'All (grey)', 'Maximum (grey)', 'Minimum (grey)', 'Range (grey)', 'Mean (grey)', 'Median (grey)'};
            app.DropDown_2.Visible = 'off';
            app.DropDown_2.Value = 'All';
            app.DiagramsTab = uitab(app.TabGroup2);
            app.DiagramsTab.Title = 'Diagrams';
            app.UIAxes = uiaxes(app.DiagramsTab);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.DiagramTree = uitree(app.DiagramsTab, 'checkbox');
            app.DiagramTree.SelectionChangedFcn = createCallbackFcn(app, @DiagramTreeSelectionChanged, true);
            app.Button = uibutton(app.DiagramsTab, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.TextArea = uitextarea(app.DiagramsTab);
            app.ReloadButton = uibutton(app.DiagramsTab, 'push');
            app.ReloadButton.ButtonPushedFcn = createCallbackFcn(app, @ReloadButtonPushed, true);
            app.ReloadButton.Text = 'Reload';
            app.DropDown_3Label = uilabel(app.DiagramsTab);
            app.DropDown_3Label.Text = 'Drop Down';
            app.DiagramTreeDropdown = uidropdown(app.DiagramsTab);
            app.DiagramTreeDropdown.Items = {'Name', 'System > Name', 'Material > Name', 'Domain > Name', 'System > Material > Name', 'System > Domain > Name', 'Material > Domain > Name', 'System > Material > Domain > Name', 'Category > Name', 'Subcategory > Name', 'DataType > Name', 'Category > Subcategory > Name', 'Category > DataType > Name', 'Subcategory > DataType > Name', 'Category > Subcategory > DataType > Name'};
            app.DiagramTreeDropdown.ValueChangedFcn = createCallbackFcn(app, @DiagramTreeDropdownValueChanged, true);
            app.DiagramTreeDropdown.Value = 'Material > Domain > Name';
            app.Hyperlink = uihyperlink(app.GridLayout3);
            app.Hyperlink.URL = 'https://github.com/NilsGies/MinPlotX';
            app.Hyperlink.Visible = 'off';
            app.Hyperlink.Text = 'This is a MinPlotX module! Find out more about MinPlotX here';
            app.MinPlotXLogo = uiimage(app.GridLayout3);
            app.MinPlotXLogo.Visible = 'off';
            app.MinPlotXLogo.ImageSource = 'MinPlotX_logo.png';
            app.DataTable_2 = uitab(app.TabGroup);
            app.DataTable_2.Title = 'DataTable';
            app.GridLayout7 = uigridlayout(app.DataTable_2);
            app.UITable = uitable(app.GridLayout7);
            app.UITable.ColumnName = '';
            app.UITable.RowName = {};
            app.SpotVisModuleButton = uibutton(app.GridLayout7, 'push');
            app.SpotVisModuleButton.ButtonPushedFcn = createCallbackFcn(app, @SpotVisModuleButtonPushed, true);
            app.SpotVisModuleButton.Visible = 'off';
            app.SpotVisModuleButton.Text = 'SpotVisModule';
            app.ResetMask = uibutton(app.GridLayout7, 'push');
            app.ResetMask.ButtonPushedFcn = createCallbackFcn(app, @ResetMaskPushed, true);
            app.ResetMask.Icon = 'symbols_1_Clear Mask.png';
            app.ResetMask.Tooltip = {'Reset filter'};
            app.ResetMask.Text = {''; ''};
            app.InvertMask = uibutton(app.GridLayout7, 'push');
            app.InvertMask.ButtonPushedFcn = createCallbackFcn(app, @InvertMaskPushed, true);
            app.InvertMask.Icon = 'symbols_1_Invert Mask.png';
            app.InvertMask.Tooltip = {'Invert filter'};
            app.InvertMask.Text = '';
            app.Add2Mask = uibutton(app.GridLayout7, 'push');
            app.Add2Mask.ButtonPushedFcn = createCallbackFcn(app, @Add2MaskPushed, true);
            app.Add2Mask.Icon = 'symbols_1_Mask Selection.png';
            app.Add2Mask.Tooltip = {'Activate selected rows'};
            app.Add2Mask.Text = '';
            app.RemoveFromMask = uibutton(app.GridLayout7, 'push');
            app.RemoveFromMask.ButtonPushedFcn = createCallbackFcn(app, @RemoveFromMaskPushed, true);
            app.RemoveFromMask.Icon = 'symbols_1_Substract from Mask.png';
            app.RemoveFromMask.Tooltip = {'Deactivate selected rows'};
            app.RemoveFromMask.Text = '';
            app.LoadTableButton = uibutton(app.GridLayout7, 'push');
            app.LoadTableButton.ButtonPushedFcn = createCallbackFcn(app, @LoadTableButtonPushed, true);
            app.LoadTableButton.Text = 'Load Table';
            app.DataCalculatorModuleButton = uibutton(app.GridLayout7, 'push');
            app.DataCalculatorModuleButton.ButtonPushedFcn = createCallbackFcn(app, @DataCalculatorModuleButtonPushed, true);
            app.DataCalculatorModuleButton.Icon = 'Caclulator.png';
            app.DataCalculatorModuleButton.Tooltip = {'DataCalculatorModule'};
            app.DataCalculatorModuleButton.Text = '';
            app.ContextMenu = uicontextmenu(app.VisModule_GUI);
            app.CheckallMenu = uimenu(app.ContextMenu);
            app.CheckallMenu.MenuSelectedFcn = createCallbackFcn(app, @CheckallMenuSelected, true);
            app.CheckallMenu.Text = 'Check all';
            app.UncheckallMenu = uimenu(app.ContextMenu);
            app.UncheckallMenu.MenuSelectedFcn = createCallbackFcn(app, @UncheckallMenuSelected, true);
            app.UncheckallMenu.Text = 'Uncheck all';
            % Assign app.ContextMenu
            app.Tree_2.ContextMenu = app.ContextMenu;
            app.VarSelectionTree.ContextMenu = app.ContextMenu;
            % Show the figure after all components are created
            app.VisModule_GUI.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = VisModule(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.VisModule_GUI)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.VisModule_GUI)
        end
    end
end