classdef VisModule < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        VisModule_GUI                  matlab.ui.Figure
        GridLayout2                    matlab.ui.container.GridLayout
        TabGroup                       matlab.ui.container.TabGroup
        PlotTab                        matlab.ui.container.Tab
        GridLayout3                    matlab.ui.container.GridLayout
        MinPlotXLogo                   matlab.ui.control.Image
        Hyperlink                      matlab.ui.control.Hyperlink
        TabGroup2                      matlab.ui.container.TabGroup
        ProfilePlotTab                 matlab.ui.container.Tab
        GridLayout5                    matlab.ui.container.GridLayout
        IndividualEasyPlotButton       matlab.ui.control.Button
        AddToEasyPlotButton            matlab.ui.control.Button
        NewEasyPlotButton              matlab.ui.control.Button
        VarSelectionTree               matlab.ui.container.CheckBoxTree
        FigureFontSizeSpinner          matlab.ui.control.Spinner
        FigureFontSizeSpinnerLabel     matlab.ui.control.Label
        FilterasNaNCheckBox            matlab.ui.control.CheckBox
        XAxesVariableDropDown          matlab.ui.control.DropDown
        XAxesVariableDropDownLabel     matlab.ui.control.Label
        DisplayFilteredCheckBox        matlab.ui.control.CheckBox
        HistogramFilterButton          matlab.ui.control.Button
        LayoutDropDown                 matlab.ui.control.DropDown
        LayoutDropDownLabel            matlab.ui.control.Label
        RowHightSpinner                matlab.ui.control.Spinner
        RowHightLabel                  matlab.ui.control.Label
        NumberofColumnsSpinner         matlab.ui.control.Spinner
        NumberofColumnsSpinnerLabel    matlab.ui.control.Label
        LineWidthSpinner               matlab.ui.control.Spinner
        LineWidthSpinnerLabel          matlab.ui.control.Label
        MarkerSizeSpinner              matlab.ui.control.Spinner
        MarkerSizeSpinnerLabel         matlab.ui.control.Label
        MarkerEdgeColorDropDown        matlab.ui.control.DropDown
        ColorDropDownLabel_3           matlab.ui.control.Label
        SymbolDropDown                 matlab.ui.control.DropDown
        LineStyleDropDown              matlab.ui.control.DropDown
        LineStyleDropDownLabel         matlab.ui.control.Label
        SymbolDropDownLabel            matlab.ui.control.Label
        LineColorDropDown              matlab.ui.control.DropDown
        ColorDropDownLabel_2           matlab.ui.control.Label
        MarkerFaceColorDropDown        matlab.ui.control.DropDown
        ColorDropDownLabel             matlab.ui.control.Label
        Panel                          matlab.ui.container.Panel
        DataSubdivisionTab             matlab.ui.container.Tab
        GridLayout6                    matlab.ui.container.GridLayout
        GeoCPlotButton                 matlab.ui.control.Button
        FigureFontSizeSpinner_2        matlab.ui.control.Spinner
        FigureFontSizeSpinner_2Label   matlab.ui.control.Label
        LabelInterpreterDropDown       matlab.ui.control.DropDown
        LabelInterpreterDropDownLabel  matlab.ui.control.Label
        CustomAxesLabelsCheckBox       matlab.ui.control.CheckBox
        CustomYLabelEditField          matlab.ui.control.EditField
        CustomYLabelEditFieldLabel     matlab.ui.control.Label
        CustomXLabelEditField          matlab.ui.control.EditField
        CustomXLabelEditFieldLabel     matlab.ui.control.Label
        AppearanceDropDown             matlab.ui.control.DropDown
        AppearanceDropDownLabel        matlab.ui.control.Label
        nEditField                     matlab.ui.control.NumericEditField
        nEditFieldLabel                matlab.ui.control.Label
        ListBox_2                      matlab.ui.control.ListBox
        ListBox                        matlab.ui.control.ListBox
        UseDataSubdivisionCheckBox     matlab.ui.control.CheckBox
        CustomPlotsTab                 matlab.ui.container.Tab
        GridLayout8                    matlab.ui.container.GridLayout
        CScaleDropDown                 matlab.ui.control.DropDown
        CScaleDropDownLabel            matlab.ui.control.Label
        ZScaleDropDown                 matlab.ui.control.DropDown
        ZScaleDropDownLabel            matlab.ui.control.Label
        YScaleDropDownLabel            matlab.ui.control.Label
        YScaleDropDown                 matlab.ui.control.DropDown
        XScaleDropDown                 matlab.ui.control.DropDown
        XScaleDropDownLabel            matlab.ui.control.Label
        FigureFontSizeSpinner_3        matlab.ui.control.Spinner
        FigureFontSizeSpinner_3Label   matlab.ui.control.Label
        LabelInterpreterDropDown_2     matlab.ui.control.DropDown
        LabelInterpreterDropDown_2Label  matlab.ui.control.Label
        DAxesVariableDropDown          matlab.ui.control.DropDown
        DAxesVariableDropDownLabel     matlab.ui.control.Label
        DLabelEditField                matlab.ui.control.EditField
        DLabelEditFieldLabel           matlab.ui.control.Label
        PlotType_DropDown              matlab.ui.control.DropDown
        PlotTypeLabel                  matlab.ui.control.Label
        ColorbarLabelEditField         matlab.ui.control.EditField
        ColorbarLabelEditFieldLabel    matlab.ui.control.Label
        ZLabelEditField                matlab.ui.control.EditField
        ZLabelEditFieldLabel           matlab.ui.control.Label
        YLabelEditField                matlab.ui.control.EditField
        YLabelEditFieldLabel           matlab.ui.control.Label
        XLabelEditField                matlab.ui.control.EditField
        XLabelEditFieldLabel           matlab.ui.control.Label
        CAxesVariableDropDown          matlab.ui.control.DropDown
        CAxesVariableDropDownLabel     matlab.ui.control.Label
        ZAxesVariableDropDown          matlab.ui.control.DropDown
        ZAxesVariableDropDownLabel     matlab.ui.control.Label
        YAxesVariableDropDown          matlab.ui.control.DropDown
        YAxesVariableDropDownLabel     matlab.ui.control.Label
        XAxesVariableDropDown_2        matlab.ui.control.DropDown
        XAxesVariableDropDown_2Label   matlab.ui.control.Label
        IndividualXYEasyPlotButton     matlab.ui.control.Button
        CustomColormapCheckBox         matlab.ui.control.CheckBox
        Panel_2                        matlab.ui.container.Panel
        GridLayout                     matlab.ui.container.GridLayout
        Options_LowerColor             matlab.ui.control.DropDown
        Options_InvertColormap         matlab.ui.control.CheckBox
        Options_ColormapDropDown       matlab.ui.control.DropDown
        ColormapDropDownLabel          matlab.ui.control.Label
        Options_LogColormap            matlab.ui.control.CheckBox
        Options_ColormapResEditField   matlab.ui.control.NumericEditField
        Options_UpperColor             matlab.ui.control.DropDown
        Options_UpperCheckBox          matlab.ui.control.CheckBox
        Options_LowerCheckBox          matlab.ui.control.CheckBox
        DisplayValuesOutsideData       matlab.ui.control.CheckBox
        NewXYEasyPlotButton_4          matlab.ui.control.Button
        TraceElementsTab               matlab.ui.container.Tab
        GridLayout9                    matlab.ui.container.GridLayout
        SigDigitsSpinner               matlab.ui.control.Spinner
        SigDigitsSpinnerLabel          matlab.ui.control.Label
        SaveNormDataButton             matlab.ui.control.Button
        FigureFontSizeSpinner_4        matlab.ui.control.Spinner
        FigureFontSizeSpinner_4Label   matlab.ui.control.Label
        DefaultsDropDown               matlab.ui.control.DropDown
        DefaultsDropDownLabel          matlab.ui.control.Label
        ElementstoPlotListBox          matlab.ui.control.ListBox
        NormToDropDown                 matlab.ui.control.DropDown
        NormToDropDownLabel            matlab.ui.control.Label
        PlotSelectedButton             matlab.ui.control.Button
        FilterElementsCheckBox         matlab.ui.control.CheckBox
        UserNormCheckBox               matlab.ui.control.CheckBox
        ListBox_4                      matlab.ui.control.ListBox
        ListBox_3                      matlab.ui.control.ListBox
        CustomNormTraceElementPlotButton  matlab.ui.control.Button
        NormTEPlotButton               matlab.ui.control.Button
        UIAxes                         matlab.ui.control.UIAxes
        ExportButton_2                 matlab.ui.control.Button
        DropDown                       matlab.ui.control.DropDown
        UpdateButton                   matlab.ui.control.Button
        Tree_2                         matlab.ui.container.CheckBoxTree
        Tree                           matlab.ui.container.CheckBoxTree
        FileInfoNode                   matlab.ui.container.TreeNode
        ChemistryNode                  matlab.ui.container.TreeNode
        MetadataNode                   matlab.ui.container.TreeNode
        apfuNode                       matlab.ui.container.TreeNode
        StructuralFormularNode         matlab.ui.container.TreeNode
        CalcOutNode                    matlab.ui.container.TreeNode
        OptionsNode                    matlab.ui.container.TreeNode
        ErrorNode                      matlab.ui.container.TreeNode
        ErrorAbsNode                   matlab.ui.container.TreeNode
        DataTable_2                    matlab.ui.container.Tab
        GridLayout7                    matlab.ui.container.GridLayout
        RemoveFromMask                 matlab.ui.control.Button
        Add2Mask                       matlab.ui.control.Button
        InvertMask                     matlab.ui.control.Button
        ResetMask                      matlab.ui.control.Button
        SpotVisModuleButton            matlab.ui.control.Button
        UITable                        matlab.ui.control.Table
        ContextMenu                    matlab.ui.container.ContextMenu
        CheckallMenu                   matlab.ui.container.Menu
        UncheckallMenu                 matlab.ui.container.Menu
    end


    properties (Access = private)
        % Description
        GridLayout_Plots % Description
        ColorMapValues
        ColorMaps % Description
        SpiderData
        MassList = { 'H1','H2','He3','He4','Li6','Li7','Be9','B10','B11',...
            'C12','C13','N14','N15','O16','O17','O18','F19','Ne20','Ne21','Ne22','Na23',...
            'Mg24','Mg25','Mg26','Al27','Si28','Si29','Si30','P31','S32','S33','S34','S36',...
            'Cl35','Cl37','Ar36','Ar38','Ar40','K39','K41','Ca40','Ca42','Ca43','Ca44','Ca46','Ca48',...
            'Sc45','Ti46','Ti47','Ti48','Ti49','Ti50','V51','Cr52','Cr53','Cr54',...
            'Mn55','Fe54','Fe56','Fe57','Fe58','Co59','Ni58','Ni60','Ni61','Ni62','Ni64',...
            'Cu63','Cu65','Zn64','Zn66','Zn67','Zn68','Zn70','Ga69','Ga71',...
            'Ge70','Ge72','Ge73','Ge74','As75','Se74','Se76','Se77','Se78','Se80',...
            'Br79','Br81','Kr78','Kr80','Kr82','Kr83','Kr84','Kr86','Rb85','Rb87',...
            'Sr84','Sr86','Sr87','Sr88','Y89','Zr90','Zr91','Zr92','Zr94','Zr96','Nb93',...
            'Mo92','Mo94','Mo95','Mo96','Mo97','Mo98','Mo100','Ru96','Ru98','Ru99','Ru100',...
            'Ru101','Ru102','Ru104','Rh103','Pd102','Pd104','Pd105','Pd106','Pd108','Pd110',...
            'Ag107','Ag109','Cd106','Cd108','Cd110','Cd111','Cd112','Cd113','Cd114','Cd116',...
            'In113','Sn112','Sn114','Sn115','Sn116','Sn117','Sn118','Sn119','Sn120','Sn122','Sn124',...
            'Sb121','Sb123','Te120','Te122','Te123','Te124','Te125','Te126','Te128','Te130',...
            'I127','Xe124','Xe126','Xe128','Xe129','Xe130','Xe131','Xe132','Xe134','Xe136',...
            'Cs133','Ba130','Ba132','Ba134','Ba135','Ba136','Ba137','Ba138','La139',...
            'Ce140','Ce142','Pr141','Nd142','Nd143','Nd144','Nd145','Nd146','Nd148','Nd150',...
            'Sm144','Sm147','Sm148','Sm149','Sm150','Sm152','Sm154','Eu151','Eu153',...
            'Gd154','Gd155','Gd156','Gd157','Gd158','Gd160','Tb159',...
            'Dy156','Dy158','Dy160','Dy161','Dy162','Dy163','Dy164','Ho165',...
            'Er166','Er167','Er168','Er170','Tm169','Yb168','Yb170','Yb171','Yb172',...
            'Yb173','Yb174','Yb176','Lu175','Lu176','Hf174','Hf176','Hf177','Hf178',...
            'Hf179','Hf180','Ta181','W180','W182','W183','W184','W186','Re185','Re187',...
            'Os187','Os188','Os189','Os190','Os192','Ir191','Ir193',...
            'Pt190','Pt192','Pt194','Pt195','Pt196','Pt198','Au197',...
            'Hg196','Hg198','Hg199','Hg200','Hg201','Hg202','Hg204','Tl203','Tl205',...
            'Pb204','Pb206','Pb207','Pb208','Bi209','Po209','At?','Rn?','Fr?','Ra226',...
            'Ac?','Th232','Pa?','U234','U235','U238','Np?','Pu?','Am?','Cm?','Bk?','Cf?',...
            'Es?','Fm?','Md?','No?','Lr?','Rf?','Db?','Sg?','Bh?','Hs?','Mt?',...
            'Uun?','Uuu?','Uub?' }; % Description
        ElementList={'H','He','Li','Be','B','C','N','O','F','Ne','Na','Mg','Al','Si','P','S','Cl',...
            'Ar','K','Ca','Sc','Ti','V','Cr','Mn','Fe','Co','Ni','Cu','Zn','Ga','Ge','As',...
            'Se','Br','Kr','Rb','Sr','Y','Zr','Nb','Mo','Tc','Ru','Rh','Pd','Ag','Cd','In',...
            'Sn','Sb','Te','I','Xe','Cs','Ba','La','Ce','Pr','Nd','Pm','Sm','Eu','Gd','Tb',...
            'Dy','Ho','Er','Tm','Yb','Lu','Hf','Ta','W','Re','Os','Ir','Pt','Au','Hg','Tl',...
            'Pb','Bi','Po','At','Rn','Fr','Ra','Ac','Th','Pa','U','Np','Pu','Am','Cm','Bk',...
            'Cf','Es','Fm','Md','No','Lr','Rf','Db','Sg','Bh','Hs','Mt','Uun','Uuu','Uub'};
        %future update add context menu edit multiple
    end

    properties (Access = public)
        MinPlotX % Description
    end

    methods (Access = private)

        function update_GUI(app)
            if not(isfield(app.MinPlotX,'Standalone'))
                UpdateFileTree(app)
                update_table(app);
            else

            end
            UpdateVariableTree(app)
        end

        function ResetTree(app,Tree2Update)

            if not(exist('Tree2Update','var'))
                Tree2Update=app.Tree_2;
            end

            %             if ~isempty(Tree2Update.SelectedNodes)
            %                 app.SelectedNodes_MainTree = Tree2Update.SelectedNodes.NodeData;
            %             end

            a = Tree2Update.Children;
            a.delete;
        end

        function UpdateVariableTree(app)

            ResetTree(app,app.VarSelectionTree)
            if isempty(app.UITable.Data)
                ColNames={};
            else
                test=any(cellfun(@isnumeric,table2cell(app.UITable.Data)));
                ColNames=app.UITable.ColumnName;
                for n=1:numel(ColNames)
                    if test(n)==true

                        uitreenode(app.VarSelectionTree,"Text",ColNames{n},'NodeData',app.UITable.Data(:,n));

                    end
                end

                ColNames=app.UITable.ColumnName(not(any(cellfun(@isnumeric,table2cell(app.UITable.Data)))|any(cellfun(@islogical,table2cell(app.UITable.Data)))));
            end
            app.ListBox.Items=ColNames;


        end

        function UpdateFileTree(app)
            if isfield(app.MinPlotX,'Standalone')
                return
            end
            Tree2Update=app.Tree_2;
            N=Tree2Update.CheckedNodes;

            if not(isempty(N))


                selectedNodes=cell2mat({N.NodeData}');

                T_C=cell2mat({Tree2Update.Children.NodeData}');
                T_C=Tree2Update.Children(T_C(ismember(T_C,unique(selectedNodes(:,1)))));
                N=[N;T_C];

                selectedNodes_T={N.Text}';
                selectedNodes=cell2mat({N.NodeData}');

                update_ts=true;
            else
                update_ts=false;
                selectedNodes=[];
            end

            %             ResetTree(app,app.Tree_2)
            %
            %             app.CheckallMenu = uimenu(app.ContextMenu);
            %             app.CheckallMenu.MenuSelectedFcn = createCallbackFcn(app, @CheckallMenuSelected, true);
            %             app.CheckallMenu.Text = 'Check all';
            %
            %             % Create UncheckallMenu
            %             app.UncheckallMenu = uimenu(app.ContextMenu);
            %             app.UncheckallMenu.MenuSelectedFcn = createCallbackFcn(app, @UncheckallMenuSelected, true);
            %             app.UncheckallMenu.Text = 'Uncheck all';
            %
            %             % Assign app.ContextMenu


            if strcmp(app.DropDown.Value,'Sort By File')

                for n=1:numel(app.MinPlotX.MinPlotXData.SampleNames)
                    p0 = uitreenode(Tree2Update,'Text',string(app.MinPlotX.MinPlotXData.SampleNames{n}),'NodeData',[n,0]);
                    %   p.ContextMenu = ContextMenu_thx;

                    p0.ContextMenu =  app.ContextMenu;

                    Mineral=unique(app.MinPlotX.MinPlotXData.Sample(n).Input.MetaTable.Mineral,'stable');
                    if numel(Mineral) > 0
                        for i = 1:numel(Mineral)

                            [T,T_InputData,T_MetaData,T_StrctFrm,T_apfu,T_CalcOut,T_options,T_FileInfo,T_err_per,T_err_abs]=get_compiled_table(app.MinPlotX,n); %#ok

                            which_row=strcmp(T_MetaData.Mineral,Mineral{i});

                            if not(isempty(T_FileInfo))
                                Data.FileInfo=T_FileInfo(which_row,:);
                            else
                                Data.FileInfo=T_FileInfo;
                            end


                            if not(isempty(T_InputData))
                                Data.InputData=T_InputData(which_row,:);
                            else
                                Data.InputData=T_InputData;
                            end

                            if not(isempty(T_MetaData))
                                Data.MetaData=T_MetaData(which_row,:);
                            else
                                Data.MetaData=T_MetaData;
                            end
                            if not(isempty(T_StrctFrm))
                                Data.StrctFrm=T_StrctFrm(which_row,:);
                            else
                                Data.StrctFrm=T_StrctFrm;
                            end

                            if not(isempty(T_apfu))
                                Data.apfu=T_apfu(which_row,:);
                            else
                                Data.apfu=T_apfu;
                            end

                            if not(isempty(T_CalcOut))
                                Data.CalcOut=T_CalcOut(which_row,:);
                            else
                                Data.CalcOut=T_CalcOut;
                            end

                            if not(isempty(T_options))
                                Data.options=T_options(which_row,:);
                            else
                                Data.options=T_options;
                            end

                            if not(isempty(T_err_abs))
                                Data.err_abs=T_err_abs(which_row,:);
                            else
                                Data.err_abs=T_err_abs;
                            end

                            if not(isempty(T_err_per))
                                Data.err_per=T_err_per(which_row,:);
                            else
                                Data.err_per=T_err_per;
                            end

                            p = uitreenode(p0,'Text',string(Mineral{i}),'NodeData',Data);


                            p.ContextMenu =  app.ContextMenu;

                            %                         if not(strcmp('unknown',char(CheckMinName(app.MinPlotX,Mineral{i}))))
                            %                             u_MNs= app.MinPlotX.MineralCalcFunctions.(char(CheckMinName(app.MinPlotX,Mineral{i})));
                            %                             for j = 1:length(u_MNs)
                            %                                 if any(not(ismissing(u_MNs{j})))
                            %                                     p1 = uitreenode(p,'Text',string(u_MNs{j}),'NodeData',[i,j]);
                            %
                            %                                     %   p1.ContextMenu = ContextMenu_thx;
                            %                                 end
                            %                             end
                            %                         end
                        end
                    end
                end
            else
                Mineral={};
                for n=1:numel(app.MinPlotX.MinPlotXData.SampleNames)
                    Mineral=unique([Mineral; app.MinPlotX.MinPlotXData.Sample(n).Input.MetaTable.Mineral],'stable');
                end

                if numel(Mineral) > 0
                    for i = 1:numel(Mineral)
                        p = uitreenode(Tree2Update,'Text',string(Mineral{i}),'NodeData',[i,0]);
                        p.ContextMenu =  app.ContextMenu;
                        for n=1:numel(app.MinPlotX.MinPlotXData.SampleNames)
                            if any(ismember(app.MinPlotX.MinPlotXData.Sample(n).Input.MetaTable.Mineral,Mineral{i}))

                                % [T,T_InputData,T_MetaData,T_StrctFrm,T_apfu,T_CalcOut,T_options,T_FileInfo]=get_compiled_table(app.MinPlotX,n); %#ok
                                [T,T_InputData,T_MetaData,T_StrctFrm,T_apfu,T_CalcOut,T_options,T_FileInfo,T_err_per,T_err_abs]=get_compiled_table(app.MinPlotX,n); %#ok

                                which_row=strcmp(T_MetaData.Mineral,Mineral{i});

                                if not(isempty(T_FileInfo))
                                    Data.FileInfo=T_FileInfo(which_row,:);
                                else
                                    Data.FileInfo=T_FileInfo;
                                end

                                if not(isempty(T_InputData))
                                    Data.InputData=T_InputData(which_row,:);
                                else
                                    Data.InputData=T_InputData;
                                end

                                if not(isempty(T_MetaData))
                                    Data.MetaData=T_MetaData(which_row,:);
                                else
                                    Data.MetaData=T_MetaData;
                                end
                                if not(isempty(T_StrctFrm))
                                    Data.StrctFrm=T_StrctFrm(which_row,:);
                                else
                                    Data.StrctFrm=T_StrctFrm;
                                end

                                if not(isempty(T_apfu))
                                    Data.apfu=T_apfu(which_row,:);
                                else
                                    Data.apfu=T_apfu;
                                end

                                if not(isempty(T_options))
                                    Data.options=T_options(which_row,:);
                                else
                                    Data.options=T_options;
                                end

                                if not(isempty(T_err_abs))
                                    Data.err_abs=T_err_abs(which_row,:);
                                else
                                    Data.err_abs=T_err_abs;
                                end

                                if not(isempty(T_err_per))
                                    Data.err_per=T_err_per(which_row,:);
                                else
                                    Data.err_per=T_err_per;
                                end

                                p0 = uitreenode(p,'Text',string(app.MinPlotX.MinPlotXData.SampleNames{n}),'NodeData',Data);
                                p0.ContextMenu =  app.ContextMenu;

                            end
                        end
                    end
                end


            end


            if update_ts==true

                try
                    TreeNodes_new={Tree2Update.Children.Text};
                    nodes2check_P=Tree2Update.Children(contains(TreeNodes_new,selectedNodes_T(selectedNodes(:,2)==0)));
                    P_old=unique(selectedNodes(selectedNodes(:,2)==0,1),'row');
                    nodes2check_final= [];
                    for n=1:numel(nodes2check_P)
                        nodes2check_P_C=(selectedNodes_T(selectedNodes(:,2)>0&selectedNodes(:,1)==P_old(n)));
                        nodes2check_final=[nodes2check_final; nodes2check_P(n).Children(contains({nodes2check_P(n).Children.Text},nodes2check_P_C))  ];%#ok
                    end
                    Tree2Update.CheckedNodes=nodes2check_final;
                    expand(Tree2Update)
                catch ME
                    DisplayError(ME,'UpdateTree')


                end

            end
        end


        function T_out=update_table(app)
            if isfield(app.MinPlotX,'Standalone')
                T_out=app.MinPlotX;

                %ATH sort_T_out_here
                if strcmp('Active',T_out.Properties.VariableNames)
                    thx=matlab.lang.makeUniqueStrings([{'Active'} T_out.Properties.VariableNames]);
                    T_out.Properties.VariableNames=thx(2:end);
                end

                app.UITable.Data=[table(true(size(T_out,1),1),'VariableNames',{'Active'}) T_out];
                app.UITable.ColumnEditable=[true false(1,size(T_out,2))];
                app.UITable.ColumnName=[{'Active'} T_out.Properties.VariableNames];
                UpdateVariableTree(app)

            else

                checkedNodes= app.Tree_2.CheckedNodes;
                if isempty(checkedNodes) || isempty(app.Tree.CheckedNodes)
                    app.UITable.Data=table();
                    return
                end

                ND_cell={checkedNodes.NodeData};
                %             ND_names={checkedNodes.Text};
                %             ND_names=ND_names(cellfun(@isstruct,ND_cell));

                ND_cell=ND_cell(cellfun(@isstruct,ND_cell));
                ND_struct=[ND_cell{:}];

                for n=1:numel(ND_cell)

                    T=table();


                    if any(ismember({app.Tree.CheckedNodes.Text}, 'FileInfo'))
                        T=[T ND_struct(n).FileInfo];%#ok
                    end

                    if any(ismember({app.Tree.CheckedNodes.Text}, 'Chemistry'))
                        T=[T ND_struct(n).InputData];%#ok
                    end

                    if any(ismember({app.Tree.CheckedNodes.Text}, 'Metadata'))

                        T=[T ND_struct(n).MetaData];%#ok
                    else
                        if not(isempty(ND_struct(n).MetaData))
                            if any(ismember(ND_struct(n).MetaData.Properties.VariableNames,'ProfileDistance'))
                                T=[T ND_struct(n).MetaData(:,ismember(ND_struct(n).MetaData.Properties.VariableNames,'ProfileDistance'))];%#ok
                            end
                            if  any(ismember(ND_struct(n).MetaData.Properties.VariableNames,'x_pos'))
                                T=[T ND_struct(n).MetaData(:,ismember(ND_struct(n).MetaData.Properties.VariableNames,'x_pos'))];%#ok
                            end
                            if any(ismember(ND_struct(n).MetaData.Properties.VariableNames,'y_pos'))
                                T=[T ND_struct(n).MetaData(:,ismember(ND_struct(n).MetaData.Properties.VariableNames,'y_pos'))];%#ok
                            end
                            if any(ismember(ND_struct(n).MetaData.Properties.VariableNames,'x_pos'))&& any(ismember(ND_struct(n).MetaData.Properties.VariableNames,'y_pos'))
                                %ATH here turn on the options for spatial plotting
                            end
                        end
                    end

                    if any(ismember({app.Tree.CheckedNodes.Text}, 'apfu'))
                        T=[T ND_struct(n).apfu];%#ok
                    end

                    if any(ismember({app.Tree.CheckedNodes.Text}, 'Structural Formular'))
                        T=[T ND_struct(n).StrctFrm];%#ok
                    end

                    if any(ismember({app.Tree.CheckedNodes.Text}, 'CalcOut'))
                        T=[T ND_struct(n).CalcOut];%#ok
                    end


                    if any(ismember({app.Tree.CheckedNodes.Text}, 'Options'))

                        T=[T ND_struct(n).options];%#ok
                    end


                    if any(ismember({app.Tree.CheckedNodes.Text}, 'Error %'))

                        T=[T ND_struct(n).err_per];%#ok

                    end

                    if any(ismember({app.Tree.CheckedNodes.Text}, 'Error Abs'))

                        T=[T ND_struct(n).err_abs];%#ok

                    end


                    T_cell{n}=T;%#ok

                end

                T_out=tablevertcat_NG(app.MinPlotX,T_cell);

                %ATH sort_T_out_here
                if strcmp('Active',T_out.Properties.VariableNames)
                    thx=matlab.lang.makeUniqueStrings([{'Active'} T_out.Properties.VariableNames]);
                    T_out.Properties.VariableNames=thx(2:end);
                end

                app.UITable.Data=[table(true(size(T_out,1),1),'VariableNames',{'Active'}) T_out];
                app.UITable.ColumnEditable=[true false(1,size(T_out,2))];
                app.UITable.ColumnName=[{'Active'} T_out.Properties.VariableNames];
            end
            UpdateVariableTree(app)
            UserNormCheckBoxValueChanged(app)
            UseDataSubdivisionCheckBoxValueChanged(app)
        end

        function symb=IterateMarkerSymbol(app,symb)
            if not(exist('symb','var'))
                symb=app.SymbolDropDown.Value;
            end

            if strcmp(symb,'o')
                symb='d';
            elseif strcmp(symb,'d')
                symb='s';
            elseif strcmp(symb,'s')
                symb='v';
            elseif strcmp(symb,'v')
                symb='>';
            elseif strcmp(symb,'>')
                symb='<';
            elseif strcmp(symb,'<')
                symb='^';
            elseif strcmp(symb,'none')
                symb='none';
            else
                symb='o';
            end
        end

        function color=IterateMarkerColor(app,color)
            if not(exist('color','var'))
                color=app.MarkerFaceColorDropDown.Value;
            end

            if any(ismember(app.MarkerFaceColorDropDown.ItemsData,color))&& find(ismember(app.MarkerFaceColorDropDown.ItemsData,color))<8

                color=app.MarkerFaceColorDropDown.ItemsData{find(ismember(app.MarkerFaceColorDropDown.ItemsData,color))+1};
            else
                color=app.MarkerFaceColorDropDown.ItemsData{1};
            end
        end


        function send_to_EasyPlot(app,settings)

            if isfield(settings,'which')
                which=settings.which;
            else
                settings.NewPlot=true;
                which='Scatter_Plot';
            end
            LineAlpha=1;

            checkedNodes = app.VarSelectionTree.CheckedNodes;
            if startsWith(which,'custom_') || not (isempty(checkedNodes))
                existing_objects={};
                ax2plot=[];
                legend_str=[];


                if    not(isfield(app.MinPlotX.MinPlotXFigures,'hFig_type')) || isempty(app.MinPlotX.MinPlotXFigures) || isempty(app.MinPlotX.MinPlotXFigures.hFig_type) % or handle not valid
                    hFig_id=1;
                elseif isfield(app.MinPlotX.MinPlotXFigures,'hFig_type') && exist('which','var') && ismember(which,app.MinPlotX.MinPlotXFigures.hFig_type) && not(isfield(settings,'NewPlot')&& settings.NewPlot==true)
                    hFig_id=find(strcmp(app.MinPlotX.MinPlotXFigures.hFig_type,which));
                    ax2plot=app.MinPlotX.MinPlotXFigures.hFig(hFig_id).ax2plot;
                    scatter_obj=flipud(findobj('Parent',ax2plot,'Type','Scatter'));
                    if not(isempty(scatter_obj))
                        existing_objects={scatter_obj.DisplayName};
                    end
                    which=app.MinPlotX.MinPlotXFigures.hFig_type{hFig_id};
                else
                    if isfield(app.MinPlotX.MinPlotXFigures,'hFig_type') && not(isempty(app.MinPlotX.MinPlotXFigures.hFig_type)) && ismember(which,app.MinPlotX.MinPlotXFigures.hFig_type)
                        names_unique = matlab.lang.makeUniqueStrings([app.MinPlotX.MinPlotXFigures.hFig_type which]);
                        which=names_unique{end};
                        %ath end with XYZ
                    end

                    hFig_id=numel(app.MinPlotX.MinPlotXFigures.hFig_type)+1;
                end


                if isempty(ax2plot)||not(isgraphics(ax2plot))
                    if startsWith(which,'custom_XYZ')
                        settings.XYZplot.Value=true;
                        [ax2plot,~]=plot_XYZ_fun([],settings);
                        hfig=ax2plot.Parent(1).Parent(1);
                    elseif startsWith(which,'custom_XY')
                        settings.XYplot.Value=true;
                        [ax2plot,~]=plot_XY_fun([],settings);
                        hfig=ax2plot.Parent(1).Parent(1);
                    elseif startsWith(which,'custom_tern')
                        settings.plot_tern.Value=true;
                        [ax2plot,~]=plot_tern_fun([],settings);
                        hfig=ax2plot.Parent(1).Parent(1);
                    elseif startsWith(which,'custom_tetra')
                        settings.tetraplot.Value=true;
                        [ax2plot,~]=plot_tetra_fun([],settings);
                        hfig=ax2plot.Parent(1).Parent(1);
                    elseif startsWith(which,'custom_TraceElements')

                        [ax2plot,~]=plot_TE_fun([],settings);
                        hfig=ax2plot.Parent(1).Parent(1);
                    else
                        hfig=figure;
                        ax2plot=nexttile;
                    end
                    try
                        CenterFig_fun(hfig)
                    catch
                    end
                    try
                        if isfield(app.MinPlotX,'Standalone')
                            hfig.CloseRequestFcn = @(src, event) EasyPlotCloseRequestFcn([],src, event);
                        else
                            hfig.CloseRequestFcn = @(src, event) EasyPlotCloseRequestFcn(app.MinPlotX,src, event);
                        end
                    catch
                        delete(hfig);
                        return
                    end

                    if startsWith(which,'custom_XYZ')
                        hfig.Name='Custom XYZ Plot';
                    elseif startsWith(which,'custom_XY')
                        hfig.Name='Custom XY Plot';
                    elseif startsWith(which,'custom_tern')
                        hfig.Name='Custom Ternary Plot';
                    elseif startsWith(which,'custom_tetra')
                        hfig.Name='Custom Tetrahedron Plot';
                    elseif startsWith(which,'custom_TraceElements')
                        if isfield(settings,'TENorm')&&isfield(settings.TENorm,'Name')
                            hfig.Name=settings.TENorm.Name;
                        else
                            hfig.Name='Trace Element Plot';
                        end
                    else
                        hfig.Name='Scatter Plot';
                    end
                    hfig.Position(3:4)=hfig.Position(3:4).*1.5;
                    hold on

                end


                if  startsWith(which,'custom_')
                    if isfield(settings,'InputTable') && not(isempty(settings.InputTable))
                        InputTable=settings.InputTable;
                    else
                        InputTable=app.UITable.Data;
                    end


                    T=InputTable;
                    symb=app.SymbolDropDown.Value;
                    mfc=app.MarkerFaceColorDropDown.Value;
                    u_c_f= InputTable.Active;


                    if  isfield(settings,'SubDiv') && settings.SubDiv==true && not(isempty(app.ListBox.Value))&& not(isempty(app.ListBox_2.Value)) && not(isempty(ismember(InputTable.(char(app.ListBox.Value)),app.ListBox_2.Value)))
                        T_data=InputTable.(char(app.ListBox.Value));
                        T_data(cellfun(@isempty, T_data))={' '};

                        u_t=unique(T_data(ismember(T_data,app.ListBox_2.Value)),'stable');
T_=T;
                        for k=1:numel(unique(u_t))
                            T=T_;
                            u_c_f=ismember(T_data,u_t(k))& InputTable.Active;
                            if app.FilterasNaNCheckBox.Value
                                T(~u_c_f,(any(cellfun(@isnumeric,table2cell(T)))))={nan};
                            else
                                T=InputTable(u_c_f,:);
                            end
                            settings.plot_settings.symbol=symb;
                            settings.plot_settings.MarkerFaceColor=mfc;

                            if startsWith(which,'custom_tern')
                                [ax2plot,~]=plot_tern_data_fun(T,ax2plot,settings);
                            elseif startsWith(which,'custom_tetra')
                                [ax2plot,~]=plot_tetra_data_fun(T,ax2plot,settings);
                            elseif startsWith(which,'custom_XYZ')
                                [ax2plot,~]=plot_XYZ_data_fun(T,ax2plot,settings);
                            elseif startsWith(which,'custom_XY')
                                [ax2plot,~]=plot_XY_data_fun(T,ax2plot,settings);
                            elseif startsWith(which,'custom_TraceElements')
                                %   [ax2plot,~]=plot_TE_data_fun(T,ax2plot,settings);
                                Update_TE_plot(app,T,ax2plot,settings)
                            end

                            %    [ax2plot,~]=plot_XYZ_data_fun(T,ax2plot,settings);
                            legend_str=[legend_str {[u_t{k}]}];%#ok
                            symb=IterateMarkerSymbol(app,symb);
                            mfc=IterateMarkerColor(app,mfc);

                        end
                    else

                        %                         if app.FilterasNaNCheckBox.Value
                        %                             T(~u_c_f,(any(cellfun(@isnumeric,table2cell(T)))))={nan};
                        %                         else
                        %                             T=T(u_c_f,:);
                        %                         end
                        settings.plot_settings.symbol=symb;
                        settings.plot_settings.MarkerFaceColor=mfc;
                        if startsWith(which,'custom_tern')
                            [ax2plot,~]=plot_tern_data_fun(T(u_c_f,:),ax2plot,settings);
                        elseif startsWith(which,'custom_tetra')
                            [ax2plot,~]=plot_tetra_data_fun(T(u_c_f,:),ax2plot,settings);
                        elseif startsWith(which,'custom_XYZ')
                            [ax2plot,~]=plot_XYZ_data_fun(T(u_c_f,:),ax2plot,settings);
                        elseif startsWith(which,'custom_XY')
                            [ax2plot,~]=plot_XY_data_fun(T(u_c_f,:),ax2plot,settings);
                        elseif startsWith(which,'custom_TraceElements')
                            % [ax2plot,~]=plot_TE_data_fun(T(u_c_f,:),ax2plot,settings);
                            Update_TE_plot(app,T(u_c_f,:),ax2plot,settings)
                        end
                        try
                            legend_str=[legend_str settings.title];
                        catch
                        end

                        if isempty(legend_str)
                            legend_str='data';
                        end
                    end

                    if app.DisplayFilteredCheckBox.Value && not(isempty(InputTable(~InputTable.Active,:)))

                        %h=plot(,'Color','r','Marker','x','MarkerFaceColor','r','MarkerEdgeColor','r','LineStyle','none','LineWidth',app.LineWidthSpinner.Value,'MarkerSize',app.MarkerSizeSpinner.Value);

                        settings.plot_settings.symbol='x';
                        settings.plot_settings.MarkerEdgeColor=[1 0 0];
                        settings.plot_settings.MarkerLineWidth.Value=2.5;
                        u_c_f= InputTable.Active;

                        if startsWith(which,'custom_tern')
                            [ax2plot,~]=plot_tern_data_fun(InputTable(~u_c_f,:),ax2plot,settings);
                        elseif startsWith(which,'custom_tetra')
                            [ax2plot,~]=plot_tetra_data_fun(InputTable(~u_c_f,:),ax2plot,settings);
                        elseif startsWith(which,'custom_XYZ')
                            [ax2plot,~]=plot_XYZ_data_fun(InputTable(~u_c_f,:),ax2plot,settings);
                        elseif startsWith(which,'custom_XY')
                            [ax2plot,~]=plot_XY_data_fun(InputTable(~u_c_f,:),ax2plot,settings);
                        elseif startsWith(which,'custom_TraceElements')
                            %[ax2plot,~]=plot_TE_data_fun(InputTable(~u_c_f,:),ax2plot,settings);
                            Update_TE_plot(app,InputTable(~u_c_f,:),ax2plot,settings)

                        end
                        legend_str=[legend_str {'Filtered'}];
                    end
                else

                    vars2plot={app.VarSelectionTree.CheckedNodes.Text};

                    if isfield(settings,'InputTable') && not(isempty(settings.InputTable))
                        InputTable=settings.InputTable;
                    else

                        InputTable=app.UITable.Data;
                    end


                    if  not(ismember(app.XAxesVariableDropDown.Value,InputTable.Properties.VariableNames))
                        x=1:size(InputTable,1);
                    else
                        x=InputTable.(char(app.XAxesVariableDropDown.Value));
                    end

                    if app.UseDataSubdivisionCheckBox.Value==true && not(isempty(app.ListBox.Value)) %%
                        T_data=InputTable.(char(app.ListBox.Value));
                        T_data(cellfun(@isempty, T_data))={' '};
                        u_t=unique(T_data(ismember(T_data,app.ListBox_2.Value)),'stable');
                    else
                        T_data.UseDataSubdivisionCheckBox=false;
                        u_t='';
                    end

                    mfa=.8;
                    mea=.8;
                    mfc=app.MarkerFaceColorDropDown.Value;
                    mec=app.MarkerEdgeColorDropDown.Value;
                    symb=app.SymbolDropDown.Value;

                    if strcmp(app.AppearanceDropDown.Value,'Color Symbol')

                        for n=1:numel(vars2plot)
                            y=InputTable.(char(vars2plot(n)));
                            u_c_f=InputTable.Active;


                            if  isfield(settings,'SubDiv') && settings.SubDiv==true && not(isempty(app.ListBox_2.Value)) && not(isempty(ismember(T_data,app.ListBox_2.Value)))
                                for k=1:numel(unique(u_t))

                                    u_c_f=ismember(T_data,u_t(k))& InputTable.Active;
                                    if app.FilterasNaNCheckBox.Value
                                        x_plot=x;
                                        y_plot=y;
                                        x_plot(~u_c_f)=nan;
                                        y_plot(~u_c_f)=nan;
                                    else
                                        x_plot=x(u_c_f);
                                        y_plot=y(u_c_f);
                                    end

 
                                    if startsWith(which,'Profile_Plot')
                                        plot(ax2plot,x_plot,y_plot,'Color',[mfc LineAlpha],'Marker',symb,'MarkerFaceColor',mfc,'MarkerEdgeColor',mec,'LineStyle',app.LineStyleDropDown.Value,'LineWidth',app.LineWidthSpinner.Value,'MarkerSize',app.MarkerSizeSpinner.Value,'Tag',[vars2plot{n} '_' u_t{k}]);
                                    else
                                        scatter(ax2plot,x_plot,y_plot,app.MarkerSizeSpinner.Value*10,symb,'filled','MarkerFaceAlpha',mfa,'MarkerEdgeAlpha',mea,'MarkerEdgeColor',mec,'MarkerFaceColor',mfc)
                                    end


                                     legend_str=[legend_str {[vars2plot{n} '_' u_t{k}]}];%#ok
                                    symb=IterateMarkerSymbol(app,symb);
                                    %
                                end
                                symb=app.SymbolDropDown.Value;

                            else
                                if app.FilterasNaNCheckBox.Value
                                    x_plot=x;
                                    y_plot=y;
                                    x_plot(~u_c_f)=nan;
                                    y_plot(~u_c_f)=nan;
                                else
                                    x_plot=x(u_c_f);
                                    y_plot=y(u_c_f);
                                end


                                if startsWith(which,'Profile_Plot')
                                    plot(ax2plot,x_plot,y_plot,'Color',[mfc LineAlpha],'Marker',symb,'MarkerFaceColor',mfc,'MarkerEdgeColor',mec,'LineStyle',app.LineStyleDropDown.Value,'LineWidth',app.LineWidthSpinner.Value,'MarkerSize',app.MarkerSizeSpinner.Value,'Tag',vars2plot{n});
                                else
                                    scatter(ax2plot,x_plot,y_plot,app.MarkerSizeSpinner.Value*10,symb,'filled','MarkerFaceAlpha',mfa,'MarkerEdgeAlpha',mea,'MarkerEdgeColor',mec,'MarkerFaceColor',mfc)
                                end

                                legend_str=[legend_str {vars2plot{n}}];%#ok
                                symb=IterateMarkerSymbol(app,symb);

                            end

                            if app.DisplayFilteredCheckBox.Value && not(isempty(x(~InputTable.Active)))
                                if startsWith(which,'Profile_Plot')
                                    plot(ax2plot,x(~InputTable.Active),y(~InputTable.Active),'Color',[mfc LineAlpha],'Marker','x','MarkerFaceColor','r','MarkerEdgeColor','r','LineStyle','none','LineWidth',2,'MarkerSize',app.MarkerSizeSpinner.Value,'Tag','filtered');
                                else
                                    scatter(ax2plot,x(~InputTable.Active),y(~InputTable.Active),app.MarkerSizeSpinner.Value*10,'x','filled','MarkerFaceAlpha',mfa,'MarkerEdgeAlpha',mea,'MarkerEdgeColor','r','MarkerFaceColor','r','LineWidth',2)                    %h=plot(ax2plot,x(~InputTable.Active),y(~InputTable.Active),'Color','r','Marker','x','MarkerFaceColor','r','MarkerEdgeColor','r','LineStyle','none','LineWidth',app.LineWidthSpinner.Value,'MarkerSize',app.MarkerSizeSpinner.Value);
                                end
                                legend_str=[legend_str {[vars2plot{n} ' Filtered']}];%#ok
                            end
                            mfc=IterateMarkerColor(app,mfc);
                            mec=IterateMarkerColor(app,mec);

                        end

                    else

                        symb=app.SymbolDropDown.Value;
                        u_c_f= InputTable.Active;

                        for n=1:numel(vars2plot)

                            y=InputTable.(char(vars2plot(n)));


                            if  isfield(settings,'SubDiv') && settings.SubDiv==true && not(isempty(app.ListBox_2.Value)) && not(isempty(ismember(T_data,app.ListBox_2.Value)))
                                %isfield(settings,'SubDiv') && settings.SubDiv==true
                                for k=1:numel(unique(u_t))
                                    u_c_f=ismember(T_data,u_t(k))& InputTable.Active;
                                    if app.FilterasNaNCheckBox.Value
                                        x_plot=x;
                                        y_plot=y;
                                        x_plot(~u_c_f)=nan;
                                        y_plot(~u_c_f)=nan;
                                    else
                                        x_plot=x(u_c_f);
                                        y_plot=y(u_c_f);
                                    end

                                    if startsWith(which,'Profile_Plot')
                                        plot(ax2plot,x_plot,y_plot,'Color',[mfc LineAlpha],'Marker',symb,'MarkerFaceColor',mfc,'MarkerEdgeColor',mec,'LineStyle',app.LineStyleDropDown.Value,'LineWidth',app.LineWidthSpinner.Value,'MarkerSize',app.MarkerSizeSpinner.Value,'Tag',vars2plot{n});
                                    else
                                        scatter(ax2plot,x_plot,y_plot,app.MarkerSizeSpinner.Value*10,symb,'filled','MarkerFaceAlpha',mfa,'MarkerEdgeAlpha',mea,'MarkerEdgeColor',mec,'MarkerFaceColor',mfc)
                                    end
                                    legend_str=[legend_str {[vars2plot{n} '_' u_t{k}]}];%#ok
                                    mfc=IterateMarkerColor(app,mfc);
                                end
                                symb=IterateMarkerSymbol(app,symb);
                            else

                                if app.FilterasNaNCheckBox.Value
                                    x_plot=x;
                                    y_plot=y;
                                    x_plot(~u_c_f)=nan;
                                    y_plot(~u_c_f)=nan;
                                else
                                    x_plot=x(u_c_f);
                                    y_plot=y(u_c_f);
                                end
                                if startsWith(which,'Profile_Plot')
                                    plot(ax2plot,x_plot,y_plot,'Color',[mfc LineAlpha],'Marker',symb,'MarkerFaceColor',app.MarkerFaceColorDropDown.ItemsData{n},'MarkerEdgeColor',app.MarkerEdgeColorDropDown.ItemsData{n},'LineStyle',app.LineStyleDropDown.Value,'LineWidth',app.LineWidthSpinner.Value,'MarkerSize',app.MarkerSizeSpinner.Value,'Tag',vars2plot{n});
                                else
                                    scatter(ax2plot,x_plot,y_plot,app.MarkerSizeSpinner.Value*10,symb,'filled','MarkerFaceAlpha',mfa,'MarkerEdgeAlpha',mea,'MarkerEdgeColor',app.MarkerEdgeColorDropDown.ItemsData{n},'MarkerFaceColor',app.MarkerFaceColorDropDown.ItemsData{n})
                                end
                                
                                legend_str=[legend_str {vars2plot{n}}];%#ok
                                symb=IterateMarkerSymbol(app,symb);
                            end
                            if app.DisplayFilteredCheckBox.Value && not(isempty(x(~InputTable.Active)))
                                 if startsWith(which,'Profile_Plot')
                                    plot(ax2plot,x(~InputTable.Active),y(~InputTable.Active),'Color','r','Marker','x','MarkerFaceColor','r','MarkerEdgeColor','r','LineStyle','none','LineWidth',2,'MarkerSize',app.MarkerSizeSpinner.Value,'Tag','Filtered');
                                else
                                scatter(ax2plot,x(~InputTable.Active),y(~InputTable.Active),app.MarkerSizeSpinner.Value*10,'x','filled','MarkerFaceAlpha',mfa,'MarkerEdgeAlpha',mea,'MarkerEdgeColor','r','MarkerFaceColor','r','LineWidth',2)                    %h=plot(ax2plot,x(~InputTable.Active),y(~InputTable.Active),'Color','r','Marker','x','MarkerFaceColor','r','MarkerEdgeColor','r','LineStyle','none','LineWidth',app.LineWidthSpinner.Value,'MarkerSize',app.MarkerSizeSpinner.Value);
                                 end
                                 legend_str=[legend_str {[vars2plot{n} ' Filtered']}];%#ok
                            end
                        end
                    end


                    if isfield(settings,'title')
                        title(ax2plot,settings.title,'Rotation',0,'FontWeight','bold','Color','k','Interpreter','none');
                    end
                    pbaspect(ax2plot,'auto');

                    if app.CustomAxesLabelsCheckBox.Value
                        xlabel(ax2plot,app.CustomXLabelEditField.Value,'interpreter',app.LabelInterpreterDropDown.Value)
                        ylabel(ax2plot,app.CustomYLabelEditField.Value,'interpreter',app.LabelInterpreterDropDown.Value)
                    else
                        xlabel(ax2plot,app.XAxesVariableDropDown.Value,'interpreter',app.LabelInterpreterDropDown.Value)
                    end

                    try
                        if not(isempty(x(InputTable.Active))) && min(x(InputTable.Active))< max(x(InputTable.Active)) && app.DisplayFilteredCheckBox.Value && not(isempty(x(~InputTable.Active)))
                            ax2plot.XLim=[min(x) max(x)];
                        elseif not(isempty(x(InputTable.Active))) && min(x(InputTable.Active))< max(x(InputTable.Active))
                            ax2plot.XLim=[min(x(InputTable.Active)) max(x(InputTable.Active))];
                        end

                    catch ME
                        DisplayError(ME,'XLim Update Error')
                    end


                    drawnow

                    options.type.vars2plot=vars2plot;

                end

                if startsWith(which,'custom_TraceElements')

                    %%%%

                    lines = flipud(findall(ax2plot, 'Type', 'line'));

                    % Get unique tags
                    tags = get(lines, 'Tag');
                    if ischar(tags)
                        tags = {tags};  % convert single char to cell array
                    end


                    % Filter out empty tags
                    nonEmptyMask = ~cellfun(@isempty, tags);
                    lines = lines(nonEmptyMask);
                    tags = tags(nonEmptyMask);

                    % Get unique tags and group lines
                    [~, ~, tagIdx] = unique(tags, 'stable');

                    % Get first occurrence of each tag
                    representativeLines = lines(accumarray(tagIdx(:), (1:length(tagIdx))', [], @min));
                    if not(iscell(legend_str))
                        legend_str={legend_str};
                    end

                    for i = 1:numel(representativeLines)
                        % Update all Tags with the new LegendString
                        set(lines(strcmp(tags, representativeLines(i).Tag)), 'Tag', legend_str{i}, 'DisplayName', legend_str{i});
                    end

                    legend(ax2plot,(representativeLines),[existing_objects legend_str],'interpreter','none') % ATH check if flipud


                    %%%%
                else
                    legend(ax2plot,[existing_objects legend_str],'interpreter','none')

                end

                options.type.Value=which;
                options.type.description=which;

                ax2plot.FontSize=app.FigureFontSizeSpinner.Value;


                app.MinPlotX.MinPlotXFigures.hFig(hFig_id).options=options;
                app.MinPlotX.MinPlotXFigures.hFig_type{hFig_id}=which;
                app.MinPlotX.MinPlotXFigures.hFig_description{hFig_id}=which;
                app.MinPlotX.MinPlotXFigures.hFig(hFig_id).ax2plot=ax2plot;

                hfig.Visible=true;
                try
                    if isfield(app.MinPlotX,'Standalone')
                        EPM=EasyPlotModule(app.MinPlotX,app);

                        EPM.MinPlotXEasyPlot_public_update(app.MinPlotX.MinPlotXFigures);
                    else

                        callEasyPlotModule(app.MinPlotX)
                    end
                catch ME
                    DisplayError(ME)
                    
                end


                %                     try
                %                     callEasyPlotModule(app.MinPlotX)
                %                     catch
                %                     end
                % end
            end

        end

        function ReadColorMaps(app)
            if isdeployed
                XMap_ColorMaps_path=fullfile(app.MinPlotX.installpath,'Dev','XMT','XMap_ColorMaps.txt');
            else
                XMap_ColorMaps_path=which(fullfile('Dev','XMT','XMap_ColorMaps.txt'));

                if isempty(XMap_ColorMaps_path)
                    XMap_ColorMaps_path=which('XMap_ColorMaps.txt');
                end
            end

            fid = fopen(XMap_ColorMaps_path,'r');

            Compt = 0;

            app.ColorMaps(1).Name = 'None';
            app.ColorMaps(1).Code = 0;

            while 1
                tline = fgetl(fid);

                if isequal(tline,-1)
                    break
                end

                if length(tline) >= 1
                    if isequal(tline(1),'>')
                        Compt = Compt+1;
                        app.ColorMaps(Compt).Name = tline(3:end);
                        Row = 0;
                        while 1
                            tline = fgetl(fid);
                            if isequal(tline,-1) || isequal(tline,'')
                                break
                            end
                            NUM = strread(tline,'%f'); %# ok
                            Row = Row+1;
                            app.ColorMaps(Compt).Code(Row,1:3) = NUM(1:3);
                        end

                    end
                end
            end
            fclose(fid);

            % Update the menu
            Items=cell(1,numel(app.ColorMaps));
            for i = 1:numel(app.ColorMaps)
                Items{i} = app.ColorMaps(i).Name;
            end
            app.Options_ColormapDropDown.Items =  Items; %extractfield(ColorMaps,'Name');

        end

        function [ColorMap] = PlotMap_UpdateColormap(app,Resolution,~)
            if not(exist('Resolution','var')) || isempty(Resolution)
                Resolution=app.Options_ColormapResEditField.Value;
            end


            SelColorMap = find(ismember(app.Options_ColormapDropDown.Items,app.Options_ColormapDropDown.Value));
            if app.Options_InvertColormap.Value
                ColorData =app.ColorMaps(SelColorMap).Code;
            else
                ColorData =flip(app.ColorMaps(SelColorMap).Code);
            end
            if Resolution==1
                ColorMap = [0,0,0];
            else
                Step = (Resolution-1)/(size(ColorData,1)-1);
                X = 1:Step:Resolution;


                ColorMap = zeros(length(1:Resolution),size(ColorData,2));
                for i = 1:size(ColorData,2)
                    ColorMap(:,i) = interp1(X',ColorData(:,i),1:Resolution);
                end

                % upper & lower
                if app.Options_LowerCheckBox.Value
                    if isequal(app.Options_LowerColor.Value,'Black')
                        ColorMap(1,:) = [0,0,0];
                    else
                        ColorMap(1,:) = [1,1,1];
                    end
                end
                if app.Options_UpperCheckBox.Value
                    if isequal(app.Options_UpperColor.Value,'Black')
                        ColorMap(end,:) = [0,0,0];
                    else
                        ColorMap(end,:) = [1,1,1];
                    end
                end
            end
            %log
            %linlogchange start

            %  app.ColorScale = 'linear';

            if not(exist('no_update','var'))
                app.ColorMapValues = ColorMap;
            end
        end

        function ReadTEData(app)

            fid = fopen(which('XMap_SpiderNormalizations.txt'),'r');
            Compt = 0;

            app.SpiderData.Names = {};
            app.SpiderData.Data(1).El = 'None';
            app.SpiderData.Data(1).Conc = 0;

          %  ErrorLoad = 0;
            while 1
                tline = fgetl(fid);

                if isequal(tline,-1)
                    break
                end

                if length(tline) >= 1
                    if isequal(tline(1),'>')
                        Str1 = tline(3:end);
                        Str2 = strread(fgetl(fid),'%s');
                        Str3 = strread(fgetl(fid),'%s');

                        if isequal(length(Str2),length(Str3))
                            Compt = Compt+1;
                            app.SpiderData.Names{Compt} = Str1;
                            app.SpiderData.Data(Compt).El = Str2;
                            Int3 = [];
                            for i=1:length(Str3)
                                Int3(i) = str2num(Str3{i});
                            end
                            app.SpiderData.Data(Compt).Conc = Int3;

                        else
                          %  ErrorLoad = 1;
                            break
                        end
                    end
                end

            end


            T2=array2table(nan(numel(app.SpiderData.Names),numel(app.ElementList)),'VariableNames',app.ElementList);

            T=table();
            T.Name=app.SpiderData.Names';

            T_out=[T T2];

            for n=1:size(T,1)
                for m=1:numel(app.SpiderData.Data(n).El)
                    T_out(n,ismember(T_out.Properties.VariableNames,app.SpiderData.Data(n).El{m}))={app.SpiderData.Data(n).Conc(m)};
                end
            end

            app.NormToDropDown.Items=app.SpiderData.Names;
        end

        function Update_TE_plot(app,T_input,ax2plot,options)

            if not(exist('options','var'))
                [options]=plot_TE_fun([],[]);
            end
            % if ismember options.types.options and   value   plot_TE_data_fun
            %    options.type.Value=value;
            %   plot_TE_data_fun(T_input,app.UIAxes)
            % else
            %    options.type.Value='Custom'
            %
            dataid=find(strcmp(app.SpiderData.Names,app.NormToDropDown.Value));

            %app.DefaultsDropDown.Value='Custom'


            if not(isfield(options,'Element_List'))
                options.Element_List=app.SpiderData.Data(dataid).El;
                options.TENorm.NormState=true;
                options.TENorm.Values=app.SpiderData.Data(dataid).Conc;
                options.TENorm.Elements=app.SpiderData.Data(dataid).El;
                options.TENorm.Name=app.NormToDropDown.Value;
            else
                %  ax2plot.Title.String='';

            end

            try
                ax2plot.YaxisLabel.String=options.TENorm.Name;
            catch

            end

            T_plot=T_input;
            plot_TE_data_fun(T_plot,ax2plot,options);
            ax2plot.Visible=true;


        end


    end

    methods (Access = public)

        function update_plot_fun(app,~)
            VarSelectionTreeCheckedNodesChanged(app)
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            movegui(app.VisModule_GUI, 'center');

            if istable(mainapp)
                % app.UITable.Visible=false;
                %     app.VarSelectionTree.Layout.Row=[1 4];
                app.TabGroup2.Layout.Column(1)=1;
                app.Tree.Visible=false;
                app.Tree_2.Visible=false;
                app.DropDown.Visible=false;
                app.MinPlotX=struct();
                app.MinPlotX.Standalone=true;
                app.MinPlotX.MinPlotXFigures=struct();
                if not(any(strcmp('Active',mainapp.Properties.VariableNames)))
                    T=table();
                    T.Active(1:size(mainapp,1))=true;
                    mainapp=[T mainapp];
                end
                app.UITable.Data=mainapp;
                app.UITable.ColumnName=mainapp.Properties.VariableNames;
                app.TabGroup2.SelectedTab= app.TabGroup2.Children(end);
                app.UpdateButton.Visible=false;
                app.Hyperlink.Visible=true;
                app.MinPlotXLogo.Visible=true;
                
            else
                app.Tree.Visible=true;
                app.Tree_2.Visible=true;
                app.DropDown.Visible=true;
                app.MinPlotX=mainapp;
            end

            ReadColorMaps(app)
            ReadTEData(app)
            update_GUI(app)

            app.TabGroup.Visible=true;

        end

        % Button pushed function: HistogramFilterButton
        function HistHistogramFilterButtonPushed(app, event)

            fun=@(app,state)(update_plot_fun(app,state));
            HistogramFilterModule(app,app.UITable,fun)


        end

        % Callback function: DropDown, UpdateButton
        function UpdateButtonPushed(app, event)
            %ResetTree(app,app.Tree_2)
            VarSelectionTreeCheckedNodesChanged(app)

        end

        % Callback function: Tree, Tree_2
        function Tree_2CheckedNodesChanged(app, event)
            update_table(app);
        end

        % Menu selected function: CheckallMenu
        function CheckallMenuSelected(app, event)
            app.Tree_2.CheckedNodes= app.Tree_2.Children;
            update_table(app);

        end

        % Menu selected function: UncheckallMenu
        function UncheckallMenuSelected(app, event)
            app.Tree_2.CheckedNodes=[];
            update_table(app);
        end

        % Callback function: DisplayFilteredCheckBox, FigureFontSizeSpinner, 
        % FilterasNaNCheckBox, LayoutDropDown, LineColorDropDown, 
        % LineStyleDropDown, LineWidthSpinner, MarkerEdgeColorDropDown, 
        % MarkerFaceColorDropDown, MarkerSizeSpinner, 
        % NumberofColumnsSpinner, RowHightSpinner, SymbolDropDown, 
        % VarSelectionTree
        function VarSelectionTreeCheckedNodesChanged(app, event)
            checkedNodes = app.VarSelectionTree.CheckedNodes;
            % cla(app.UIAxes_big)
            app.Panel.Visible=false;
            if not (isempty(checkedNodes))
                symb=app.SymbolDropDown.Value;

                vars2plot={app.VarSelectionTree.CheckedNodes.Text};
                stacked=strcmp(app.LayoutDropDown.Value,'Stacked');

                if stacked
                    n_col=1;
                    n_row=1;
                    Scrollable_state='off';

                elseif strcmp(app.LayoutDropDown.Value,'Individual')
                    InputTable=app.UITable.Data;
                    T_data=InputTable.(char(app.ListBox.Value));
                    T_data(cellfun(@isempty, T_data))={' '};

                    u_t=unique(T_data(ismember(T_data,app.ListBox_2.Value)),'stable');

                    n_col=app.NumberofColumnsSpinner.Value;
                    n_row=(ceil(numel(u_t)/n_col));
                    Scrollable_state='off';

                else
                    n_col=app.NumberofColumnsSpinner.Value;
                    n_row=(ceil(numel(vars2plot)/n_col));
                    Scrollable_state='off';
                end
                app.GridLayout_Plots=uigridlayout(app.Panel,[n_row n_col]);

                if not(stacked)

                  %  rowhight=app.RowHightSpinner.Value;

                    %         A = rowhight*ones(1,n_row);
                    %         app.GridLayout_Plots.RowHeight="fit";%A;
                    app.GridLayout_Plots.RowHeight=repmat({'1x'},1,n_row);

                end
                %
                app.GridLayout_Plots.ColumnWidth=repmat({'1x'},1,n_col);
                set(app.GridLayout_Plots,'Scrollable', Scrollable_state);


                if not(ismember(app.XAxesVariableDropDown.Value,app.UITable.Data.Properties.VariableNames))
                    x=1:size(app.UITable.Data,1);
                else
                    x=app.UITable.Data.(char(app.XAxesVariableDropDown.Value));
                end


                if strcmp(app.LayoutDropDown.Value,'Individual')

                    for k=1:numel(u_t)
                        symb=app.SymbolDropDown.Value;

                        legend_str={};
                        ax2plot=uiaxes(app.GridLayout_Plots, 'HandleVisibility','on');

                        ax2plot.NextPlot='add';
                        ax2plot.FontSize=app.FigureFontSizeSpinner.Value;

                        pbaspect(ax2plot,'auto');
                        mfc=app.MarkerFaceColorDropDown.Value;

                        mec= app.MarkerEdgeColorDropDown.Value;
                        filered_val=false;
                        for n=1:numel(vars2plot)
                            y=app.UITable.Data.(char(vars2plot(n)));

                            if app.FilterasNaNCheckBox.Value
                                u_c_f=ismember(T_data,u_t(k)) ;

                                x_plot=x(u_c_f);
                                y_plot=y(u_c_f);

                                x_plot(~(app.UITable.Data.Active(u_c_f)))=nan;
                                y_plot(~(app.UITable.Data.Active(u_c_f)))=nan;
                            else
                                u_c_f=ismember(T_data,u_t(k)) &app.UITable.Data.Active;

                                x_plot=x(u_c_f);
                                y_plot=y(u_c_f);
                            end
                            h=plot(ax2plot,x_plot,y_plot,'Color',mec,'Marker',symb,'MarkerFaceColor',mfc,'MarkerEdgeColor',mec,'LineStyle',app.LineStyleDropDown.Value,'LineWidth',app.LineWidthSpinner.Value,'MarkerSize',app.MarkerSizeSpinner.Value);

                            if app.DisplayFilteredCheckBox.Value && not(isempty(x(~app.UITable.Data.Active & ismember(T_data,u_t(k)))))
                                h=plot(ax2plot,x(ismember(T_data,u_t(k)) & ~app.UITable.Data.Active),y(ismember(T_data,u_t(k)) & ~app.UITable.Data.Active),'Color','r','Marker','x','MarkerFaceColor','r','MarkerEdgeColor','r','LineStyle','none','LineWidth',app.LineWidthSpinner.Value,'MarkerSize',app.MarkerSizeSpinner.Value);
                                filered_val=true;
                            end

                            symb=IterateMarkerSymbol(app,symb);
                            mfc=IterateMarkerColor(app,mfc);
                            mec=IterateMarkerColor(app,mec);
                        end
                        title(ax2plot,u_t{k},'Rotation',0,'FontWeight','bold','Color','k','Interpreter','none');

                        if app.DisplayFilteredCheckBox.Value && not(isempty(x(~app.UITable.Data.Active))) && filered_val==true
                            legend_str=vars2plot;
                            legend_str(end+1)={'Filtered'};
                            h=get(ax2plot,'Children'); % grab all the axes handles at once
                            legend(flipud(h([1,2:2:end])),legend_str,'interpreter','none')

                        else
                            legend(ax2plot,vars2plot,'interpreter','none')
                        end

                        %                         if app.CustomAxesLabelsCheckBox.Value
                        %                             xlabel(ax2plot,app.CustomXLabelEditField.Value,'interpreter',app.LabelInterpreterDropDown.Value)
                        %                             ylabel(ax2plot,app.CustomYLabelEditField.Value,'interpreter',app.LabelInterpreterDropDown.Value)
                        %                         else
                        %                             xlabel(ax2plot,app.XAxesVariableDropDown.Value,'interpreter',app.LabelInterpreterDropDown.Value)
                        %                         end
                        if ax2plot.Layout.Row==numel(app.GridLayout_Plots.RowHeight)
                            if app.CustomAxesLabelsCheckBox.Value
                                xlabel(ax2plot,app.CustomXLabelEditField.Value,'interpreter',app.LabelInterpreterDropDown.Value)
                                ylabel(ax2plot,app.CustomYLabelEditField.Value,'interpreter',app.LabelInterpreterDropDown.Value)
                            else
                                xlabel(ax2plot,app.XAxesVariableDropDown.Value,'interpreter',app.LabelInterpreterDropDown.Value)

                            end
                        end
                        try
                            if not(isempty(x(u_c_f))) && min(x(u_c_f))< max(x(u_c_f))
                                ax2plot.XLim=[min(x(u_c_f)) max(x(u_c_f))];
                            end
                        catch ME
                            DisplayError(ME,'XLim Update Error')

                        end
                    end
                else

                    mfc=app.MarkerFaceColorDropDown.Value;

                    mec= app.MarkerEdgeColorDropDown.Value;
                    filered_val=false;

                    for n=1:numel(vars2plot)
                        if stacked && n==1
                            ax2plot=uiaxes(app.GridLayout_Plots, 'HandleVisibility','on');
                            ax2plot.NextPlot='add';
                            ax2plot.FontSize=app.FigureFontSizeSpinner.Value;
                        elseif stacked==false
                            ax2plot = uiaxes(app.GridLayout_Plots, 'HandleVisibility','on');
                            pbaspect(ax2plot,'auto');
                            ax2plot.NextPlot='add';
                            ax2plot.FontSize=app.FigureFontSizeSpinner.Value;

                            title(ax2plot,vars2plot(n),'Rotation',0,'FontWeight','bold','Color','k','Interpreter','none');

                        end
                        y=app.UITable.Data.(char(vars2plot(n)));

                        if app.FilterasNaNCheckBox.Value
                            x_plot=x;
                            y_plot=y;
                            x_plot(~app.UITable.Data.Active)=nan;
                            y_plot(~app.UITable.Data.Active)=nan;
                        else
                            x_plot=x(app.UITable.Data.Active);
                            y_plot=y(app.UITable.Data.Active);
                        end

                        h=plot(ax2plot,x_plot,y_plot,'Color',mec,'Marker',symb,'MarkerFaceColor',mfc,'MarkerEdgeColor',mec,'LineStyle',app.LineStyleDropDown.Value,'LineWidth',app.LineWidthSpinner.Value,'MarkerSize',app.MarkerSizeSpinner.Value);

                        symb=IterateMarkerSymbol(app,symb);
                        mfc=IterateMarkerColor(app,mfc);
                        mec=IterateMarkerColor(app,mec);

                        if app.DisplayFilteredCheckBox.Value && not(isempty(x(~app.UITable.Data.Active)))
                            h=plot(ax2plot,x(~app.UITable.Data.Active),y(~app.UITable.Data.Active),'Color','r','Marker','x','MarkerFaceColor','r','MarkerEdgeColor','r','LineStyle','none','LineWidth',app.LineWidthSpinner.Value,'MarkerSize',app.MarkerSizeSpinner.Value);
                            filered_val=true;
                        end

                        if ax2plot.Layout.Row==numel(app.GridLayout_Plots.RowHeight)
                            if app.CustomAxesLabelsCheckBox.Value
                                xlabel(ax2plot,app.CustomXLabelEditField.Value,'interpreter',app.LabelInterpreterDropDown.Value)
                                ylabel(ax2plot,app.CustomYLabelEditField.Value,'interpreter',app.LabelInterpreterDropDown.Value)
                            else
                                xlabel(ax2plot,app.XAxesVariableDropDown.Value,'interpreter',app.LabelInterpreterDropDown.Value)

                            end
                        end

                    end

                    try
                        if not(isempty(x(app.UITable.Data.Active))) && min(x(app.UITable.Data.Active))< max(x(app.UITable.Data.Active))
                            ax2plot.XLim=[min(x(app.UITable.Data.Active)) max(x(app.UITable.Data.Active))];
                        end
                    catch ME
                        DisplayError(ME,'XLim Update Error')
                    end

                end
                if stacked
                    if app.DisplayFilteredCheckBox.Value && not(isempty(x(~app.UITable.Data.Active))) &&filered_val==true;
                        legend_str=vars2plot;
                        legend_str(end+1)={'Filtered'};
                        h=get(ax2plot,'Children'); % grab all the axes handles at once
                        legend(flipud(h([1,2:2:end])),legend_str,'interpreter','none')
                    else
                        legend(ax2plot,vars2plot,'interpreter','none')
                    end
                end

                drawnow
                app.Panel.Visible=true;

            end


        end

        % Button pushed function: NewEasyPlotButton
        function NewEasyPlotButtonPushed(app, event)

            checkedNodes = app.VarSelectionTree.CheckedNodes;
            if not (isempty(checkedNodes))
                settings.which='Profile_Plot';
                settings.NewPlot=true;
                settings.SubDiv=app.UseDataSubdivisionCheckBox.Value;

                send_to_EasyPlot(app,settings)
            end
        end

        % Button pushed function: AddToEasyPlotButton
        function AddToEasyPlotButtonPushed(app, event)
            if isfield(app.MinPlotX.MinPlotXFigures,'hFig_type') && not(isempty(app.MinPlotX.MinPlotXFigures.hFig_type)) && any(startsWith(app.MinPlotX.MinPlotXFigures.hFig_type,'Profile_Plot'))
                list= app.MinPlotX.MinPlotXFigures.hFig_type((startsWith(app.MinPlotX.MinPlotXFigures.hFig_type,'Profile_Plot')));
                if numel(list)==1
                    which=list{1};
                else
                    [indx,~] = listdlg('ListString',list,'SelectionMode','single');
                    if isempty(indx)
                        return
                    end

                    which=list{indx};
                end
                figure(app.VisModule_GUI)
                settings.Add2Plot=true;
                settings.which=which;
            else
                settings.NewPlot=true;
            end

            settings.SubDiv=app.UseDataSubdivisionCheckBox.Value;

            send_to_EasyPlot(app,settings)


        end

        % Button pushed function: IndividualEasyPlotButton
        function IndividualEasyPlotButtonPushed(app, event)
            checkedNodes = app.VarSelectionTree.CheckedNodes;

            if not(isempty(checkedNodes))

                if app.nEditField.Value>12
                    answer = uiconfirm(app.VisModule_GUI, ...
                        ['Are you sure you want to generate plots for ' num2str((app.nEditField.Value)) ' Entries?'],"Generate Plots", ...
                        "Icon","warning");

                    if strcmp(answer,'Cancel')
                        return
                    end
                end
                settings.NewPlot=true;
                settings.SubDiv=false;
                InputTable=app.UITable.Data;
                T_data=InputTable.(char(app.ListBox.Value));
                T_data(cellfun(@isempty, T_data))={' '};
                             settings.which='Profile_Plot';
   

                u_t=unique(T_data(ismember(T_data,app.ListBox_2.Value)),'stable');

                for k=1:numel(unique(u_t))

                    u_c_f=ismember(T_data,u_t(k)) ;
                    settings.InputTable=InputTable(u_c_f,:);
                    settings.title=u_t(k);
                    send_to_EasyPlot(app,settings)

                end

            end
        end

        % Value changed function: UseDataSubdivisionCheckBox
        function UseDataSubdivisionCheckBoxValueChanged(app, event)
            app.ListBox.Visible=app.UseDataSubdivisionCheckBox.Value;
            app.nEditField.Visible=app.UseDataSubdivisionCheckBox.Value;
            app.nEditFieldLabel.Visible=app.UseDataSubdivisionCheckBox.Value;
            %  app.XAxesVariableDropDown.Visible=app.UseDataSubdivisionCheckBox.Value;
            %    app.XAxesVariableDropDownLabel.Visible=app.UseDataSubdivisionCheckBox.Value;
            ListBoxValueChanged(app)
            app.IndividualEasyPlotButton.Visible=app.UseDataSubdivisionCheckBox.Value;
            app.IndividualXYEasyPlotButton.Visible=app.UseDataSubdivisionCheckBox.Value;

            if app.UseDataSubdivisionCheckBox.Value==true && not(isempty(app.ListBox.Value)) %%
                app.LayoutDropDown.Items={'Stacked','Tiled','Individual'};
            else
                %                 app.XAxesVariableDropDown.Items={'ProfileDistance','1:n'};
                %                 app.XAxesVariableDropDown.Value='1:n';

                app.LayoutDropDown.Items={'Stacked','Tiled'};
            end
        end

        % Value changed function: ListBox
        function ListBoxValueChanged(app, event)

            if ismember(app.ListBox.Value,app.UITable.Data.Properties.VariableNames)
                [x, ~]=  unique(app.UITable.Data.(char(app.ListBox.Value)),'stable');
                x(cellfun(@isempty, x))={' '};


                app.nEditField.Value=numel(x);

                if app.UseDataSubdivisionCheckBox.Value==false
                    app.ListBox_2.Visible=false;
                    app.ListBox_2.Items={};

                elseif numel(x)>50
                    app.ListBox_2.Visible=true;
                    app.ListBox_2.Items=x;
                    app.ListBox_2.Value=x(1:5);

                else
                    try
                        app.ListBox_2.Items=x;
                        app.ListBox_2.Value=x;
                    catch

                    end
                    app.ListBox_2.Visible=true;

                end
            else
                app.ListBox_2.Items={};
            end
        end

        % Value changed function: ListBox_2
        function ListBox_2ValueChanged(app, event)
            value = app.ListBox_2.Value;
            if not(isempty(value))
                app.nEditField.Value=numel(value);
            end
        end

        % Button pushed function: ExportButton_2
        function ExportButton_2Pushed(app, event)
            filePath = app.MinPlotX.WorkingDirectoryEditField.Value;
            fileName ='graphic.pdf';
            % or query for filename:
            [fileName, filePath] = uiputfile(['*.pdf'],'Export',fullfile(filePath,fileName));
            exportgraphics(app.GridLayout_Plots, fullfile(filePath,fileName),'ContentType','vector')
            %    web(fullfile(filePath,fileName))
        end

        % Drop down opening function: XAxesVariableDropDown
        function XAxesVariableDropDownOpening(app, event)
            app.XAxesVariableDropDown.Items=[{'1:n'} app.UITable.ColumnName((any(cellfun(@isnumeric,table2cell(app.UITable.Data)))|any(cellfun(@islogical,table2cell(app.UITable.Data)))))'];
        end

        % Value changed function: CustomAxesLabelsCheckBox
        function CustomAxesLabelsCheckBoxValueChanged(app, event)
            value=app.CustomAxesLabelsCheckBox.Value;
            app.CustomXLabelEditFieldLabel.Visible=value;
            app.CustomXLabelEditField.Visible=value;
            app.CustomYLabelEditFieldLabel.Visible=value;
            app.CustomYLabelEditField.Visible=value;

            % app.LabelInterpreterDropDown.Visible=value;
            %  app.LabelInterpreterDropDownLabel.Visible=value;

            app.CustomXLabelEditField.Value=app.XAxesVariableDropDown.Value;

        end

        % Button pushed function: SpotVisModuleButton
        function SpotVisModuleButtonPushed(app, event)
            app.UITable.Data.Fileindex=app.UITable.Data.SpotId;
            SpotVisMinPlotX(app, app.UITable)
        end

        % Button pushed function: NewXYEasyPlotButton_4
        function NewXYEasyPlotButton_4Pushed(app, event)

            if strcmp(app.PlotType_DropDown.Value,'XYZ')
                settings.which='custom_XYZ';
                settings.type.Value='custom_XYZ';
            elseif strcmp(app.PlotType_DropDown.Value,'XY')
                settings.which='custom_XY';
                settings.type.Value='custom_XY';
            elseif strcmp(app.PlotType_DropDown.Value,'Ternary')
                settings.which='custom_tern';
                settings.type.Value='custom_tern';
            elseif strcmp(app.PlotType_DropDown.Value,'Tetrahedron')
                settings.which='custom_tetra';
                settings.type.Value='custom_tetra';
            end


            settings.NewPlot=true;
            settings.SubDiv=app.UseDataSubdivisionCheckBox.Value;

            settings.custom.Interpreter=app.LabelInterpreterDropDown_2.Value;

            if strcmp(app.XAxesVariableDropDown_2.Value,'1:n')
                settings.X1=[];
                settings.custom.xlabel="1:n";
            else
                settings.X1=app.XAxesVariableDropDown_2.Value;
                settings.custom.xlabel=app.XLabelEditField.Value;
            end

            if strcmp(app.YAxesVariableDropDown.Value,'1:n')
                settings.Y1=[];
                settings.custom.ylabel="1:n";
            else
                settings.Y1=app.YAxesVariableDropDown.Value;
                settings.custom.ylabel=app.YLabelEditField.Value;
            end

            if strcmp(app.PlotType_DropDown.Value,'XYZ') || strcmp(app.PlotType_DropDown.Value,'Ternary') || strcmp(app.PlotType_DropDown.Value,'Tetrahedron')
                if strcmp(app.ZAxesVariableDropDown.Value,'1:n')
                    settings.custom.zlabel="1:n";
                    settings.Z1=[];
                else
                    settings.Z1=app.ZAxesVariableDropDown.Value;
                    settings.custom.zlabel=app.ZLabelEditField.Value;
                end
            end

            if  strcmp(app.PlotType_DropDown.Value,'Tetrahedron')
                if strcmp(app.DAxesVariableDropDown.Value,'1:n')
                    settings.custom.dlabel="1:n";
                    settings.D1=[];
                else
                    settings.D1=app.DAxesVariableDropDown.Value;
                    settings.custom.dlabel=app.DLabelEditField.Value;
                end
            end


            if app.CustomColormapCheckBox.Value
                if strcmp(app.CAxesVariableDropDown.Value,'1:n')
                    settings.C1=[];
                    settings.ColorData.cbar_label="1:n";
                else
                    settings.C1=app.CAxesVariableDropDown.Value;
                    settings.ColorData.cbar_label=app.ColorbarLabelEditField.Value;
                end
                settings.ColorData.Value=app.CustomColormapCheckBox.Value;
                settings.ColorData.colormap=PlotMap_UpdateColormap(app,app.Options_ColormapResEditField.Value);
            end

            if app.XScaleDropDown.Visible==true
                settings.custom.XScale=app.XScaleDropDown.Value;
                settings.custom.YScale=app.YScaleDropDown.Value;
            end

            if app.ZScaleDropDown.Visible==true
                settings.custom.ZScale=app.ZScaleDropDown.Value;
            end

            if app.CScaleDropDown.Visible==true
                settings.custom.CScale=app.CScaleDropDown.Value;
            end


            send_to_EasyPlot(app,settings)

        end

        % Button pushed function: IndividualXYEasyPlotButton
        function IndividualXYEasyPlotButtonPushed(app, event)


            if app.nEditField.Value>12
                answer = uiconfirm(app.VisModule_GUI, ...
                    ['Are you sure you want to generate plots for ' num2str((app.nEditField.Value)) ' Entries?'],"Generate Plots", ...
                    "Icon","warning");

                if strcmp(answer,'Cancel')
                    return
                end
            end


            if strcmp(app.PlotType_DropDown.Value,'XYZ')
                settings.which='custom_XYZ';
                settings.type.Value='custom_XYZ';
            elseif strcmp(app.PlotType_DropDown.Value,'XY')
                settings.which='custom_XY';
                settings.type.Value='custom_XY';
            elseif strcmp(app.PlotType_DropDown.Value,'Ternary')
                settings.which='custom_tern';
                settings.type.Value='custom_tern';
            elseif strcmp(app.PlotType_DropDown.Value,'Tetrahedron')
                settings.which='custom_tetra';
                settings.type.Value='custom_tetra';
            end


            settings.NewPlot=true;
            settings.SubDiv=false;
            InputTable=app.UITable.Data;

            if isempty(app.ListBox.Value) || isempty(app.ListBox_2.Value)
                message={'No Subdivision Selected!'};
                uialert(app.VisModule_GUI,message,'Error');
                return
            end

            T_data=InputTable.(char(app.ListBox.Value));
            T_data(cellfun(@isempty, T_data))={' '};

            u_t=unique(T_data(ismember(T_data,app.ListBox_2.Value)),'stable');


            if strcmp(app.XAxesVariableDropDown_2.Value,'1:n')
                settings.X1=[];
                settings.custom.xlabel="1:n";
            else
                settings.X1=app.XAxesVariableDropDown_2.Value;
                settings.custom.xlabel=app.XLabelEditField.Value;
            end

            if strcmp(app.YAxesVariableDropDown.Value,'1:n')
                settings.Y1=[];
                settings.custom.ylabel="1:n";
            else
                settings.Y1=app.YAxesVariableDropDown.Value;
                settings.custom.ylabel=app.YLabelEditField.Value;
            end

            if strcmp(app.PlotType_DropDown.Value,'XYZ') || strcmp(app.PlotType_DropDown.Value,'Ternary') || strcmp(app.PlotType_DropDown.Value,'Tetrahedron')
                if strcmp(app.ZAxesVariableDropDown.Value,'1:n')
                    settings.custom.zlabel="1:n";
                    settings.Z1=[];
                else
                    settings.Z1=app.ZAxesVariableDropDown.Value;
                    settings.custom.zlabel=app.ZLabelEditField.Value;
                end
            end

            if  strcmp(app.PlotType_DropDown.Value,'Tetrahedron')
                if strcmp(app.DAxesVariableDropDown.Value,'1:n')
                    settings.custom.dlabel="1:n";
                    settings.D1=[];
                else
                    settings.D1=app.DAxesVariableDropDown.Value;
                    settings.custom.dlabel=app.DLabelEditField.Value;
                end
            end


            if app.CustomColormapCheckBox.Value==true
                if strcmp(app.CAxesVariableDropDown.Value,'1:n')
                    settings.C1=[];
                    settings.ColorData.cbar_label="1:n";
                else
                    settings.C1=app.CAxesVariableDropDown.Value;
                    settings.ColorData.cbar_label=app.ColorbarLabelEditField.Value;
                end
                settings.ColorData.Value=app.CustomColormapCheckBox.Value;
                settings.ColorData.colormap=PlotMap_UpdateColormap(app,app.Options_ColormapResEditField.Value);
            end

            symb_ini=app.SymbolDropDown.Value;
            mfc_ini=app.MarkerFaceColorDropDown.Value;
            symb=app.SymbolDropDown.Value;
            mfc=app.MarkerFaceColorDropDown.Value;


            for k=1:numel(unique(u_t))
                u_c_f=ismember(T_data,u_t(k)) ;
                settings.InputTable=InputTable(u_c_f,:);
                settings.title=u_t(k);
                send_to_EasyPlot(app,settings)
                symb=IterateMarkerSymbol(app,symb);
                app.SymbolDropDown.Value=symb;
                mfc=IterateMarkerColor(app,mfc);
                app.MarkerFaceColorDropDown.Value=mfc;

            end

            app.SymbolDropDown.Value=symb_ini;
            app.MarkerFaceColorDropDown.Value=mfc_ini;

        end

        % Value changed function: PlotType_DropDown
        function PlotType_DropDownValueChanged(app, event)

            if strcmp(app.PlotType_DropDown.Value,'Tetrahedron')
                vis_state=true;
                app.NewXYEasyPlotButton_4.Text='New Tetrahedron EasyPlot';
                app.IndividualXYEasyPlotButton.Text='Individual Tetrahedron EasyPlot';

                app.XAxesVariableDropDown_2Label.Text='A Axes Variable';
                app.YAxesVariableDropDownLabel.Text='B Axes Variable';
                app.ZAxesVariableDropDownLabel.Text='C Axes Variable';
                app.CAxesVariableDropDownLabel.Text='Colormap Variable';

                app.DAxesVariableDropDown.Visible=true;
                app.DAxesVariableDropDownLabel.Visible=true;

                app.DLabelEditField.Visible=true;
                app.DLabelEditFieldLabel.Visible=true;


                app.XScaleDropDown.Value='linear';
                app.XScaleDropDown.Visible=false;
                app.XScaleDropDownLabel.Visible=false;

                app.YScaleDropDown.Value='linear';
                app.YScaleDropDown.Visible=false;
                app.YScaleDropDownLabel.Visible=false;

                app.ZScaleDropDown.Value='linear';
                app.ZScaleDropDown.Visible=false;
                app.ZScaleDropDownLabel.Visible=false;

            else
                app.XAxesVariableDropDown_2Label.Text='X Axes Variable';
                app.YAxesVariableDropDownLabel.Text='Y Axes Variable';
                app.ZAxesVariableDropDownLabel.Text='Z Axes Variable';
                app.CAxesVariableDropDownLabel.Text='C Axes Variable';


                app.DAxesVariableDropDown.Visible=false;
                app.DAxesVariableDropDownLabel.Visible=false;

                app.DLabelEditField.Visible=false;
                app.DLabelEditFieldLabel.Visible=false;

                if strcmp(app.PlotType_DropDown.Value,'XYZ')
                    vis_state=true;
                    app.NewXYEasyPlotButton_4.Text='New XYZ EasyPlot';
                    app.IndividualXYEasyPlotButton.Text='Individual XYZ EasyPlot';

                    app.XScaleDropDown.Visible=true;
                    app.XScaleDropDownLabel.Visible=true;


                    app.YScaleDropDown.Visible=true;
                    app.YScaleDropDownLabel.Visible=true;


                    app.ZScaleDropDown.Visible=true;
                    app.ZScaleDropDownLabel.Visible=true;

                elseif strcmp(app.PlotType_DropDown.Value,'XY')
                    vis_state=true;
                    app.NewXYEasyPlotButton_4.Text='New XY EasyPlot';
                    app.IndividualXYEasyPlotButton.Text='Individual XY EasyPlot';

                    app.XScaleDropDown.Visible=true;
                    app.XScaleDropDownLabel.Visible=true;

                    app.YScaleDropDown.Visible=true;
                    app.YScaleDropDownLabel.Visible=true;

                    app.ZScaleDropDown.Visible=false;
                    app.ZScaleDropDownLabel.Visible=false;
                elseif strcmp(app.PlotType_DropDown.Value,'Ternary')
                    vis_state=true;
                    app.NewXYEasyPlotButton_4.Text='New Ternary EasyPlot';
                    app.IndividualXYEasyPlotButton.Text='Individual Ternary EasyPlot';

                    app.XScaleDropDown.Value='linear';
                    app.XScaleDropDown.Visible=false;
                    app.XScaleDropDownLabel.Visible=false;

                    app.YScaleDropDown.Value='linear';
                    app.YScaleDropDown.Visible=false;
                    app.YScaleDropDownLabel.Visible=false;

                    app.ZScaleDropDown.Value='linear';
                    app.ZScaleDropDown.Visible=false;
                    app.ZScaleDropDownLabel.Visible=false;
                end
            end

            app.ColorbarLabelEditField.Visible=vis_state;

            app.ColorbarLabelEditFieldLabel.Visible=vis_state;
            if strcmp(app.PlotType_DropDown.Value,'XY')
                app.ZLabelEditFieldLabel.Visible=false;
                app.ZLabelEditField.Visible=false;
                app.ZAxesVariableDropDown.Visible=false;
                app.ZAxesVariableDropDownLabel.Visible=false;

            else
                app.ZLabelEditFieldLabel.Visible=vis_state;
                app.ZLabelEditField.Visible=vis_state;
                app.ZAxesVariableDropDown.Visible=vis_state;
                app.ZAxesVariableDropDownLabel.Visible=vis_state;
            end

            if  app.CustomColormapCheckBox.Value==true
                app.Panel_2.Visible=true;
                app.CAxesVariableDropDownLabel.Visible=true;
                app.CAxesVariableDropDown.Visible=true;
                app.ColorbarLabelEditField.Visible=true;
                app.ColorbarLabelEditFieldLabel.Visible=true;

                app.CScaleDropDown.Visible=true;
                app.CScaleDropDownLabel.Visible=true;

            else
                app.Panel_2.Visible=false;
                app.CAxesVariableDropDownLabel.Visible=false;
                app.CAxesVariableDropDown.Visible=false;
                app.ColorbarLabelEditField.Visible=false;
                app.ColorbarLabelEditFieldLabel.Visible=false;
                app.CScaleDropDown.Visible=false;
                app.CScaleDropDownLabel.Visible=false;

            end


        end

        % Drop down opening function: XAxesVariableDropDown_2
        function XAxesVariableDropDown_2Opening(app, event)
            app.XAxesVariableDropDown_2.Items=[{'1:n'} app.UITable.ColumnName((any(cellfun(@isnumeric,table2cell(app.UITable.Data)))|any(cellfun(@islogical,table2cell(app.UITable.Data)))))'];

        end

        % Value changed function: XAxesVariableDropDown_2
        function XAxesVariableDropDown_2ValueChanged(app, event)
            app.XLabelEditField.Value = app.XAxesVariableDropDown_2.Value;

        end

        % Drop down opening function: YAxesVariableDropDown
        function YAxesVariableDropDownOpening(app, event)
            app.YAxesVariableDropDown.Items=[{'1:n'} app.UITable.ColumnName((any(cellfun(@isnumeric,table2cell(app.UITable.Data)))|any(cellfun(@islogical,table2cell(app.UITable.Data)))))'];

        end

        % Value changed function: YAxesVariableDropDown
        function YAxesVariableDropDownValueChanged(app, event)
            app.YLabelEditField.Value = app.YAxesVariableDropDown.Value;

        end

        % Drop down opening function: ZAxesVariableDropDown
        function ZAxesVariableDropDownOpening(app, event)
            app.ZAxesVariableDropDown.Items=[{'1:n'} app.UITable.ColumnName((any(cellfun(@isnumeric,table2cell(app.UITable.Data)))|any(cellfun(@islogical,table2cell(app.UITable.Data)))))'];

        end

        % Value changed function: ZAxesVariableDropDown
        function ZAxesVariableDropDownValueChanged(app, event)
            app.ZLabelEditField.Value = app.ZAxesVariableDropDown.Value;

        end

        % Drop down opening function: CAxesVariableDropDown
        function CAxesVariableDropDownOpening(app, event)
            app.CAxesVariableDropDown.Items=[{'1:n'} app.UITable.ColumnName((any(cellfun(@isnumeric,table2cell(app.UITable.Data)))|any(cellfun(@islogical,table2cell(app.UITable.Data)))))'];

        end

        % Value changed function: CAxesVariableDropDown
        function CAxesVariableDropDownValueChanged(app, event)
            app.ColorbarLabelEditField.Value = app.CAxesVariableDropDown.Value;

        end

        % Drop down opening function: DAxesVariableDropDown
        function DAxesVariableDropDownOpening(app, event)
            app.DAxesVariableDropDown.Items=[{'1:n'} app.UITable.ColumnName((any(cellfun(@isnumeric,table2cell(app.UITable.Data)))|any(cellfun(@islogical,table2cell(app.UITable.Data)))))'];

        end

        % Value changed function: DAxesVariableDropDown
        function DAxesVariableDropDownValueChanged(app, event)
            app.DLabelEditField.Value = app.DAxesVariableDropDown.Value;

        end

        % Value changed function: CustomColormapCheckBox
        function CustomColormapCheckBoxValueChanged(app, event)
            if  app.CustomColormapCheckBox.Value==true
                app.Panel_2.Visible=true;
                app.CAxesVariableDropDownLabel.Visible=true;
                app.CAxesVariableDropDown.Visible=true;
                app.ColorbarLabelEditField.Visible=true;
                app.ColorbarLabelEditFieldLabel.Visible=true;

                app.CScaleDropDown.Visible=true;
                app.CScaleDropDownLabel.Visible=true;

            else
                app.Panel_2.Visible=false;
                app.CAxesVariableDropDownLabel.Visible=false;
                app.CAxesVariableDropDown.Visible=false;
                app.ColorbarLabelEditField.Visible=false;
                app.ColorbarLabelEditFieldLabel.Visible=false;
                app.CScaleDropDown.Visible=false;
                app.CScaleDropDownLabel.Visible=false;

            end
        end

        % Value changed function: XAxesVariableDropDown
        function XAxesVariableDropDownValueChanged(app, event)
            app.CustomXLabelEditField.Value=app.XAxesVariableDropDown.Value;
            VarSelectionTreeCheckedNodesChanged(app)
        end

        % Button pushed function: ResetMask
        function ResetMaskPushed(app, event)
            app.UITable.Data.Active=true(size(app.UITable.Data.Active,1),1);

        end

        % Button pushed function: InvertMask
        function InvertMaskPushed(app, event)
            app.UITable.Data.Active=not(app.UITable.Data.Active);

        end

        % Button pushed function: Add2Mask
        function Add2MaskPushed(app, event)
            if not(isempty( app.UITable.Selection))
                app.UITable.Data.Active(unique(app.UITable.Selection(:,1)))=true;
            end
        end

        % Button pushed function: RemoveFromMask
        function RemoveFromMaskPushed(app, event)
            if not(isempty( app.UITable.Selection))
                app.UITable.Data.Active(unique(app.UITable.Selection(:,1)))=false;
            end
        end

        % Drop down opening function: LayoutDropDown
        function LayoutDropDownOpening(app, event)
            if app.UseDataSubdivisionCheckBox.Value==true && not(isempty(app.ListBox.Value)) %%
                app.LayoutDropDown.Items={'Stacked','Tiled','Individual'};
            else
                app.LayoutDropDown.Items={'Stacked','Tiled'};
            end
        end

        % Value changed function: FigureFontSizeSpinner, 
        % FigureFontSizeSpinner_2, FigureFontSizeSpinner_3, 
        % FigureFontSizeSpinner_4
        function FigureFontSizeSpinnerValueChanged(app, event)

            value = event.Value;

            app.FigureFontSizeSpinner.Value=value;
            app.FigureFontSizeSpinner_2.Value=value;
            app.FigureFontSizeSpinner_3.Value=value;
            app.FigureFontSizeSpinner_4.Value=value;

        end

        % Value changed function: NormToDropDown
        function NormToDropDownValueChanged(app, event)
           cla(app.UIAxes, 'reset');
            app.UIAxes.XAxis.TickLabels=[];
            hold(app.UIAxes, 'on');

            Update_TE_plot(app,app.UITable.Data,app.UIAxes)
            app.UIAxes.Visible=true;
        end

        % Button pushed function: NormTEPlotButton
        function NormTEPlotButtonPushed(app, event)
            settings.which='custom_TraceElements';
            settings.type.Value='custom_TraceElements';

            settings.NewPlot=true;
            settings.SubDiv=app.UseDataSubdivisionCheckBox.Value;

            send_to_EasyPlot(app,settings)

        end

        % Value changed function: ListBox_3
        function ListBox_3ValueChanged(app, event)


            if ismember(app.ListBox_3.Value,app.UITable.Data.Properties.VariableNames)
                [x, ~]=  unique(app.UITable.Data.(char(app.ListBox_3.Value)),'stable');
                x(cellfun(@isempty, x))={' '};

                try
                    app.ListBox_4.Items=x;
                    app.ListBox_4.Value=x;
                catch

                end
                app.ListBox_4.Visible=true;


            else
                app.ListBox_4.Items={};
            end


        end

        % Button pushed function: CustomNormTraceElementPlotButton
        function CustomNormTraceElementPlotButtonPushed(app, event)

            [settings]=plot_TE_fun([],[]);

            value = app.NormToDropDown.Value;


            settings.which='custom_TraceElements';
            settings.type.Value=['custom_TraceElements' char(value)];

            settings.NewPlot=true;
            settings.SubDiv=app.UseDataSubdivisionCheckBox.Value;

            T=app.UITable.Data(contains(app.UITable.Data.(char(app.ListBox_3.Value)),app.ListBox_4.Value),any(cellfun(@isnumeric,table2cell(app.UITable.Data))));


            settings.TENorm.NormState=true;

            if app.FilterElementsCheckBox.Value==true && startsWith(app.DefaultsDropDown.Value,'Mass')
                Elements=app.MassList;
                headers= T.Properties.VariableNames;
            else
                Elements=app.ElementList;
                headers= replace(T.Properties.VariableNames,{'0','1','2','3','4','5','6','7','8','9'},'');
            end

            T=T(:,ismember(headers,Elements));
            T.Properties.VariableNames=matlab.lang.makeUniqueStrings(headers(ismember(headers,Elements)));

            if app.FilterElementsCheckBox.Value==false || isempty(app.ElementstoPlotListBox.Value) && isempty(app.ElementstoPlotListBox.Items)
                settings.Element_List= T.Properties.VariableNames;
            elseif  isempty(app.ElementstoPlotListBox.Value)
                settings.Element_List=app.ElementstoPlotListBox.Items;
            else
                settings.Element_List=app.ElementstoPlotListBox.Items(find(ismember(app.ElementstoPlotListBox.Items,app.ElementstoPlotListBox.Value)));
            end

            mean_data=mean(table2array(T),1,'omitnan');
            VarNames=T.Properties.VariableNames;
            % mean_data_T=array2table(mean_data,'VariableNames',thx.Properties.VariableNames);


            settings.TENorm.Values=mean_data;
            settings.TENorm.Elements=VarNames;
            settings.TENorm.Name=app.ListBox_4.Value;


            send_to_EasyPlot(app,settings)


        end

        % Value changed function: UserNormCheckBox
        function UserNormCheckBoxValueChanged(app, event)
            %ismember(app.ListBox.Value,app.UITable.Data.Properties.VariableNames)
            if isempty(app.UITable.Data)
                app.ListBox_3.Items={};
                return
            end
            value = app.UserNormCheckBox.Value;

            app.ListBox_3.Visible=app.UserNormCheckBox.Value;
            app.ListBox_4.Visible=app.UserNormCheckBox.Value;
            app.CustomNormTraceElementPlotButton.Visible=app.UserNormCheckBox.Value;

            app.SaveNormDataButton.Visible=app.UserNormCheckBox.Value;
            app.SigDigitsSpinner.Visible=app.UserNormCheckBox.Value;
            app.SigDigitsSpinnerLabel.Visible=app.UserNormCheckBox.Value;


            app.ListBox_3.Items=app.UITable.ColumnName(not(any(cellfun(@isnumeric,table2cell(app.UITable.Data)))|any(cellfun(@islogical,table2cell(app.UITable.Data)))));
            if ismember(app.ListBox_3.Value,app.UITable.Data.Properties.VariableNames)
                [x, ~]=  unique(app.UITable.Data.(char(app.ListBox_3.Value)),'stable');
                x(cellfun(@isempty, x))={' '};


                if app.UseDataSubdivisionCheckBox.Value==false
                    app.ListBox_4.Visible=false;
                    app.ListBox_4.Items={};
                else
                    try
                        app.ListBox_4.Items=x;
                        app.ListBox_4.Value=x;
                    catch

                    end

                end
            else
                app.ListBox_4.Items={};
            end
        end

        % Value changed function: ListBox_4
        function ListBox_4ValueChanged(app, event)
           cla(app.UIAxes, 'reset');
            app.UIAxes.XAxis.TickLabels=[];
            hold(app.UIAxes, 'on');

            [settings]=plot_TE_fun([],[]);

            
            settings.which='custom_TraceElements';
            settings.type.Value='custom_TraceElements';

            settings.NewPlot=true;
            settings.SubDiv=app.UseDataSubdivisionCheckBox.Value;
            
            T=app.UITable.Data(contains(app.UITable.Data.(char(app.ListBox_3.Value)),app.ListBox_4.Value),any(cellfun(@isnumeric,table2cell(app.UITable.Data))));

            if app.FilterElementsCheckBox.Value==false || isempty(app.ElementstoPlotListBox.Value) && isempty(app.ElementstoPlotListBox.Items)
                headers= replace(T.Properties.VariableNames,{'0','1','2','3','4','5','6','7','8','9'},'');
                headers=headers(ismember(headers,app.ElementList));
                settings.Element_List= headers;
         
            elseif  isempty(app.ElementstoPlotListBox.Value)
                settings.Element_List=app.ElementstoPlotListBox.Items;
            else
                settings.Element_List=app.ElementstoPlotListBox.Items(find(ismember(app.ElementstoPlotListBox.Items,app.ElementstoPlotListBox.Value)));
            end


            %settings.Element_List=app.ElementstoPlotListBox.Items;

            settings.TENorm.NormState=true;


            if app.FilterElementsCheckBox.Value==true && startsWith(app.DefaultsDropDown.Value,'Mass')
                Elements=app.MassList;
                headers= T.Properties.VariableNames;
            else
                Elements=app.ElementList;
                headers= replace(T.Properties.VariableNames,{'0','1','2','3','4','5','6','7','8','9'},'');
            end

            T=T(:,ismember(headers,Elements));
            T.Properties.VariableNames=matlab.lang.makeUniqueStrings(headers(ismember(headers,Elements)));


            mean_data=mean(table2array(T));
            VarNames=T.Properties.VariableNames;
            % mean_data_T=array2table(mean_data,'VariableNames',thx.Properties.VariableNames);


            settings.TENorm.Values=mean_data;
            settings.TENorm.Elements=VarNames;
            settings.TENorm.Name=app.ListBox_4.Value;


            Update_TE_plot(app,app.UITable.Data,app.UIAxes,settings)

        end

        % Value changed function: FilterElementsCheckBox
        function FilterElementsCheckBoxValueChanged(app, event)
            value = app.FilterElementsCheckBox.Value;
            app.ElementstoPlotListBox.Visible=value;
            app.PlotSelectedButton.Visible=value;
            app.DefaultsDropDown.Visible=value;
            app.DefaultsDropDownLabel.Visible=value;
            DefaultsDropDownValueChanged(app)
        end

        % Value changed function: DefaultsDropDown
        function DefaultsDropDownValueChanged(app, event)

            switch  app.DefaultsDropDown.Value

                case'All Elements'
                    Elements=app.ElementList;

                    app.ElementstoPlotListBox.Items=Elements;

                case'REE'
                    app.ElementstoPlotListBox.Items={'La', 'Ce', 'Pr', 'Nd', 'Sm', 'Eu', 'Gd', 'Tb', 'Dy', 'Ho', 'Er', 'Tm', 'Yb', 'Lu'};
                case'REE (Ionic Radii)'
                    app.ElementstoPlotListBox.Items={'La', 'Ce', 'Pr', 'Nd', 'Sm', 'Eu', 'Gd', 'Tb', 'Dy', 'Ho', 'Er', 'Tm', 'Yb', 'Lu'};
                case'Trace Elements'
                    app.ElementstoPlotListBox.Items={'Li','Be','Na','Mg','Al','Si','K','Ca','Sc','Ti','V','Cr','Mn','Fe','Co','Ni','Cu','Zn','As','Rb','Sr','Y','Zr','Nb','Mo','Ag','In','Cs','Ba','La','Ce','Pr','Nd','Sm','Eu','Gd','Tb','Dy','Ho','Er','Tm','Yb','Lu','Hf','Ta','W','Pb','Bi','U'};
                case 'Spider'
                    app.ElementstoPlotListBox.Items={ 'Th','U','Nb','La','Ce','Sr','P','Nd','Zr','Hf','Sm','Eu','Tb','Ti','Y','Yb','Lu'};
                case {'Elements in table','Element data in table'}
                    Elements=app.ElementList;

                    T=app.UITable.Data;
                    headers= replace(T.Properties.VariableNames,{'0','1','2','3','4','5','6','7','8','9'},'');
                    T=T(:,ismember(headers,Elements));

                    Element_Names=headers(ismember(headers,app.ElementList));%;


                    app.ElementstoPlotListBox.Items=app.ElementList(ismember(app.ElementList,Element_Names));

                    if strcmp(app.DefaultsDropDown.Value,'Element data in table')

                        if app.UseDataSubdivisionCheckBox.Value==true
                            T=T(contains(app.UITable.Data.(char(app.ListBox.Value)),app.ListBox_2.Value),:);
                        end

                        %    headers=headers(ismember(headers,Elements));
                        %   headers=headers(any(cellfun(@isnumeric,table2cell(T))));
                        %

                        %                         T(:,not(sum(table2array(T(:,any(cellfun(@isnumeric,table2cell(T)))))==0)==size(T,1)));


                        app.ElementstoPlotListBox.Items=replace(T(:,not(sum(table2array(T(:,any(cellfun(@isnumeric,table2cell(T)))))==0)==size(T,1))).Properties.VariableNames,{'0','1','2','3','4','5','6','7','8','9'},'');
                    end
                    

                case {'Masses in table','Mass data in table','Mass data in table ~0'}


                    T=app.UITable.Data;
                    headers= T.Properties.VariableNames;
                    T=T(:,ismember(headers,app.MassList));


                    Element_Names=headers(ismember(headers,app.MassList));%;


                    app.ElementstoPlotListBox.Items = app.MassList(ismember(app.MassList,Element_Names));

                    if strcmp(app.DefaultsDropDown.Value,'Mass data in table')

                        if app.UseDataSubdivisionCheckBox.Value==true
                            T=T(contains(app.UITable.Data.(char(app.ListBox.Value)),app.ListBox_2.Value),:);
                        end

                        %    headers=headers(ismember(headers,app.MassList));
                        %   headers=headers(any(cellfun(@isnumeric,table2cell(T))));
                        %

                        %                         T(:,not(sum(table2array(T(:,any(cellfun(@isnumeric,table2cell(T)))))==0)==size(T,1)));
                        headers=T(:,not(sum(table2array(T(:,any(cellfun(@isnumeric,table2cell(T)))))==0)==size(T,1))).Properties.VariableNames;

                    elseif strcmp(app.DefaultsDropDown.Value,'Mass data in table ~0')
                        if app.UseDataSubdivisionCheckBox.Value==true
                            T=T(contains(app.UITable.Data.(char(app.ListBox.Value)),app.ListBox_2.Value),:);
                        end

                        A = table2array(T(:, varfun(@isnumeric, T, 'OutputFormat','uniform')));
                        V = T.Properties.VariableNames(varfun(@isnumeric, T, 'OutputFormat','uniform'));
                        headers= V(~(all(A == 0 | isnan(A))));

                    end
                    masses_names=headers(ismember(headers,app.MassList));%;

                    headers= app.MassList(ismember(app.MassList,masses_names));
                    app.ElementstoPlotListBox.Items=headers;
                    
                    %case 'Custom'


            end
                  
            app.ElementstoPlotListBox.Value=app.ElementstoPlotListBox.Items;

        end

        % Button pushed function: PlotSelectedButton
        function PlotSelectedButtonPushed(app, event)
            settings.which='custom_TraceElements';
            settings.type.Value='custom_TraceElements';

            if app.FilterElementsCheckBox.Value==false || isempty(app.ElementstoPlotListBox.Value) && isempty(app.ElementstoPlotListBox.Items)
                headers= replace(T.Properties.VariableNames,{'0','1','2','3','4','5','6','7','8','9'},'');
                headers=headers(ismember(headers,app.ElementList));
                settings.Element_List= headers;

            elseif  isempty(app.ElementstoPlotListBox.Value)
                settings.Element_List=app.ElementstoPlotListBox.Items;
            else
                settings.Element_List=app.ElementstoPlotListBox.Items(find(ismember(app.ElementstoPlotListBox.Items,app.ElementstoPlotListBox.Value)));
            end

            if strcmp(app.DefaultsDropDown.Value,'REE (Ionic Radii)')
                x=[1 1.01466 1.02975 1.04529 1.07357 1.08650 1.09975 1.11333 1.12725 1.14042 1.15278 1.16426 1.17480 1.18434];
                if not(isempty(app.ElementstoPlotListBox.Value))
                    settings.xValues=x(ismember(app.ElementstoPlotListBox.Items,app.ElementstoPlotListBox.Value));
                else
                    settings.xValues=x;
                end
            end
            settings.NewPlot=true;
            settings.SubDiv=app.UseDataSubdivisionCheckBox.Value;
            settings.ylabel.String='[μg/g]';


            send_to_EasyPlot(app,settings)


        end

        % Button pushed function: GeoCPlotButton
        function GeoCPlotButtonPushed(app, event)
            if app.UseDataSubdivisionCheckBox.Value==true
                T=app.UITable.Data(contains(app.UITable.Data.(char(app.ListBox.Value)),app.ListBox_2.Value),:);
            else
                T=app.UITable.Data;
            end

            if not(isempty(T))
                GeoCPlot(T)
            end

        end

        % Button pushed function: SaveNormDataButton
        function SaveNormDataButtonPushed(app, event)
            
            if isempty(app.ListBox_4.Value)
               return
            end

            T=app.UITable.Data(contains(app.UITable.Data.(char(app.ListBox_3.Value)),app.ListBox_4.Value),any(cellfun(@isnumeric,table2cell(app.UITable.Data))));


            settings.TENorm.NormState=true;

            if app.FilterElementsCheckBox.Value==true && startsWith(app.DefaultsDropDown.Value,'Mass')
                Elements=app.MassList;
                headers= T.Properties.VariableNames;
            else
                Elements=app.ElementList;
                headers= replace(T.Properties.VariableNames,{'0','1','2','3','4','5','6','7','8','9'},'');
            end

            T=T(:,ismember(headers,Elements));
            T.Properties.VariableNames=matlab.lang.makeUniqueStrings(headers(ismember(headers,Elements)));

            mean_data=mean(table2array(T),1,'omitnan');
            VarNames=T.Properties.VariableNames;

            message=replace(['> ' char(app.ListBox_3.Value) ' - ' char(app.ListBox_4.Value) newline char(join(VarNames,' ')) newline mat2str(round(mean_data,app.SigDigitsSpinner.Value))],{'[',']'},'');
            

            % Create a UIFigure
hfig = uifigure;
CenterFig_fun(hfig)
% Create a grid layout with 3 rows and 3 columns
grid = uigridlayout(hfig, [3, 3]);

% Set row heights and column widths
grid.RowHeight = {25, '1x', 45};  % First row takes the height of text, second row auto, third row takes button height
grid.ColumnWidth = {'1x', 100, 55};  % Equal column width for 3 columns

% Create a label in the first row spanning all 3 columns
lbl = uilabel(grid);
lbl.Text = 'Copy the string and add it to the file: /installationpath/MinPlotX/application/Dev/XMap_SpiderNormalizations.txt';
lbl.Layout.Row = 1;
lbl.Layout.Column = [1, 3];  % Spans across all columns

% Create a textarea in the second row, spanning all 3 columns
t = uitextarea(grid);
t.Value = {message};  % Default value
t.Layout.Row = 2;
t.Layout.Column = [1, 3];  % Spans across all columns

% Create a grid for the third row to hold the buttons (2 buttons in 3 columns)
buttonGrid = uigridlayout(grid, [1, 3]);
buttonGrid.Layout.Row = 3;
buttonGrid.Layout.Column = [1, 3];  % Span the entire third row

% Create the "Copy to clipboard" button
copyBtn = uibutton(buttonGrid, 'Text', 'Copy to Clipboard');
copyBtn.Layout.Column = 1;  % First column in the button grid

% Create the "Close" button
closeBtn = uibutton(buttonGrid, 'Text', 'Close');
closeBtn.Layout.Column = 3;  % Third column in the button grid


% Add button callbacks (example)
copyBtn.ButtonPushedFcn = @(src, event) disp('Copied to clipboard');
closeBtn.ButtonPushedFcn = @(src, event) close(hfig);


% Callback for "Copy to Clipboard" button
copyBtn.ButtonPushedFcn = @(src, event) clipboard('copy', char(join(t.Value(:),newline)));
% Callback for "Close" button
closeBtn.ButtonPushedFcn = @(src, event) close(hfig);


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.VisModule_GUI = uifigure('Visible', 'off');
            app.VisModule_GUI.Name = 'VisModule';
            app.GridLayout2 = uigridlayout(app.VisModule_GUI);
            app.TabGroup = uitabgroup(app.GridLayout2);
            app.TabGroup.Visible = 'off';
            app.PlotTab = uitab(app.TabGroup);
            app.PlotTab.Title = 'PlotTab';
            app.GridLayout3 = uigridlayout(app.PlotTab);
            app.Tree = uitree(app.GridLayout3, 'checkbox');
            app.Tree.Visible = 'off';
            app.FileInfoNode = uitreenode(app.Tree);
            app.FileInfoNode.Text = 'FileInfo';
            app.ChemistryNode = uitreenode(app.Tree);
            app.ChemistryNode.Text = 'Chemistry';
            app.MetadataNode = uitreenode(app.Tree);
            app.MetadataNode.Text = 'Metadata';
            app.apfuNode = uitreenode(app.Tree);
            app.apfuNode.Text = 'apfu';
            app.StructuralFormularNode = uitreenode(app.Tree);
            app.StructuralFormularNode.Text = 'Structural Formular';
            app.CalcOutNode = uitreenode(app.Tree);
            app.CalcOutNode.Text = 'CalcOut';
            app.OptionsNode = uitreenode(app.Tree);
            app.OptionsNode.Text = 'Options';
            app.ErrorNode = uitreenode(app.Tree);
            app.ErrorNode.Text = 'Error %';
            app.ErrorAbsNode = uitreenode(app.Tree);
            app.ErrorAbsNode.Text = 'Error Abs';
            % Assign Checked Nodes
            app.Tree.CheckedNodes = [app.FileInfoNode, app.ChemistryNode, app.MetadataNode];
            % Assign Checked Nodes
            app.Tree.CheckedNodesChangedFcn = createCallbackFcn(app, @Tree_2CheckedNodesChanged, true);
            app.Tree_2 = uitree(app.GridLayout3, 'checkbox');
            % Assign Checked Nodes
            app.Tree_2.CheckedNodesChangedFcn = createCallbackFcn(app, @Tree_2CheckedNodesChanged, true);
            app.UpdateButton = uibutton(app.GridLayout3, 'push');
            app.UpdateButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateButtonPushed, true);
            app.UpdateButton.Text = 'Update';
            app.DropDown = uidropdown(app.GridLayout3);
            app.DropDown.Items = {'Sort By File', 'Sort By Mineral'};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @UpdateButtonPushed, true);
            app.DropDown.Value = 'Sort By File';
            app.ExportButton_2 = uibutton(app.GridLayout3, 'push');
            app.ExportButton_2.ButtonPushedFcn = createCallbackFcn(app, @ExportButton_2Pushed, true);
            app.ExportButton_2.Text = 'Export';
            app.TabGroup2 = uitabgroup(app.GridLayout3);
            app.ProfilePlotTab = uitab(app.TabGroup2);
            app.ProfilePlotTab.Title = 'Profile Plot';
            app.GridLayout5 = uigridlayout(app.ProfilePlotTab);
            app.Panel = uipanel(app.GridLayout5);
            app.ColorDropDownLabel = uilabel(app.GridLayout5);
            app.ColorDropDownLabel.Text = 'MarkerFaceColor';
            app.MarkerFaceColorDropDown = uidropdown(app.GridLayout5);
            app.MarkerFaceColorDropDown.Items = {'blue', 'orange', 'yellow', 'purple', 'green', 'cyan', 'red', 'black', 'none'};
            app.MarkerFaceColorDropDown.ItemsData = {'[0 0.4470 0.7410]', '[0.8500 0.3250 0.0980]', '[0.9290 0.6940 0.1250]', '[0.4940 0.1840 0.5560]', '[0.4660 0.6740 0.1880]', '[0.3010 0.7450 0.9330]', '[0.6350 0.0780 0.1840]', '[0 0 0]', 'none'};
            app.MarkerFaceColorDropDown.ValueChangedFcn = createCallbackFcn(app, @VarSelectionTreeCheckedNodesChanged, true);
            app.MarkerFaceColorDropDown.Value = '[0 0.4470 0.7410]';
            app.ColorDropDownLabel_2 = uilabel(app.GridLayout5);
            app.ColorDropDownLabel_2.Text = 'LineColor';
            app.LineColorDropDown = uidropdown(app.GridLayout5);
            app.LineColorDropDown.Items = {'blue', 'orange', 'yellow', 'purple', 'green', 'cyan', 'red', 'black'};
            app.LineColorDropDown.ItemsData = {'[0 0.4470 0.7410]', '[0.8500 0.3250 0.0980]', '[0.9290 0.6940 0.1250]', '[0.4940 0.1840 0.5560]', '[0.4660 0.6740 0.1880]', '[0.3010 0.7450 0.9330]', '[0.6350 0.0780 0.1840]', '[0 0 0]'};
            app.LineColorDropDown.ValueChangedFcn = createCallbackFcn(app, @VarSelectionTreeCheckedNodesChanged, true);
            app.LineColorDropDown.Value = '[0 0.4470 0.7410]';
            app.SymbolDropDownLabel = uilabel(app.GridLayout5);
            app.SymbolDropDownLabel.Text = 'MarkerSymbol';
            app.LineStyleDropDownLabel = uilabel(app.GridLayout5);
            app.LineStyleDropDownLabel.Text = 'LineStyle';
            app.LineStyleDropDown = uidropdown(app.GridLayout5);
            app.LineStyleDropDown.Items = {'No Line', 'Solid line', 'Dashed line', 'Dotted line', 'Dash-dotted line'};
            app.LineStyleDropDown.ItemsData = {'none ', '-', '--', ':', '-.'};
            app.LineStyleDropDown.ValueChangedFcn = createCallbackFcn(app, @VarSelectionTreeCheckedNodesChanged, true);
            app.LineStyleDropDown.Value = '-';
            app.SymbolDropDown = uidropdown(app.GridLayout5);
            app.SymbolDropDown.Items = {'Circle', 'Plus sign', 'Asterisk', 'Point', 'Cross', 'Horizontal line', 'Vertical line', 'Square', 'Diamond', 'Upward-pointing triangle', 'Downward-pointing triangle', 'Right-pointing triangle', 'Left-pointing triangle', 'Pentagram', 'Hexagram', 'none'};
            app.SymbolDropDown.ItemsData = {'o', '+', '*', '.', 'x', '_', '|', 's', 'd', '^', 'v', '>', '<', 'p', 'h', 'none'};
            app.SymbolDropDown.ValueChangedFcn = createCallbackFcn(app, @VarSelectionTreeCheckedNodesChanged, true);
            app.SymbolDropDown.Value = 'o';
            app.ColorDropDownLabel_3 = uilabel(app.GridLayout5);
            app.ColorDropDownLabel_3.Text = 'MarkerEdgeColor';
            app.MarkerEdgeColorDropDown = uidropdown(app.GridLayout5);
            app.MarkerEdgeColorDropDown.Items = {'blue', 'orange', 'yellow', 'purple', 'green', 'cyan', 'red', 'black', 'none'};
            app.MarkerEdgeColorDropDown.ItemsData = {'[0 0.4470 0.7410]', '[0.8500 0.3250 0.0980]', '[0.9290 0.6940 0.1250]', '[0.4940 0.1840 0.5560]', '[0.4660 0.6740 0.1880]', '[0.3010 0.7450 0.9330]', '[0.6350 0.0780 0.1840]', '[0 0 0]', 'none'};
            app.MarkerEdgeColorDropDown.ValueChangedFcn = createCallbackFcn(app, @VarSelectionTreeCheckedNodesChanged, true);
            app.MarkerEdgeColorDropDown.Value = '[0 0.4470 0.7410]';
            app.MarkerSizeSpinnerLabel = uilabel(app.GridLayout5);
            app.MarkerSizeSpinnerLabel.Text = 'MarkerSize';
            app.MarkerSizeSpinner = uispinner(app.GridLayout5);
            app.MarkerSizeSpinner.Limits = [1 1000];
            app.MarkerSizeSpinner.ValueChangedFcn = createCallbackFcn(app, @VarSelectionTreeCheckedNodesChanged, true);
            app.MarkerSizeSpinner.Value = 10;
            app.LineWidthSpinnerLabel = uilabel(app.GridLayout5);
            app.LineWidthSpinnerLabel.Text = 'LineWidth';
            app.LineWidthSpinner = uispinner(app.GridLayout5);
            app.LineWidthSpinner.Limits = [1 100];
            app.LineWidthSpinner.ValueChangedFcn = createCallbackFcn(app, @VarSelectionTreeCheckedNodesChanged, true);
            app.LineWidthSpinner.Value = 1;
            app.NumberofColumnsSpinnerLabel = uilabel(app.GridLayout5);
            app.NumberofColumnsSpinnerLabel.Text = 'Number of Columns';
            app.NumberofColumnsSpinner = uispinner(app.GridLayout5);
            app.NumberofColumnsSpinner.Limits = [1 20];
            app.NumberofColumnsSpinner.ValueChangedFcn = createCallbackFcn(app, @VarSelectionTreeCheckedNodesChanged, true);
            app.NumberofColumnsSpinner.Value = 2;
            app.RowHightLabel = uilabel(app.GridLayout5);
            app.RowHightLabel.Visible = 'off';
            app.RowHightLabel.Text = 'RowHight';
            app.RowHightSpinner = uispinner(app.GridLayout5);
            app.RowHightSpinner.Step = 10;
            app.RowHightSpinner.Limits = [1 20000];
            app.RowHightSpinner.ValueChangedFcn = createCallbackFcn(app, @VarSelectionTreeCheckedNodesChanged, true);
            app.RowHightSpinner.Visible = 'off';
            app.RowHightSpinner.Value = 200;
            app.LayoutDropDownLabel = uilabel(app.GridLayout5);
            app.LayoutDropDownLabel.Text = 'Layout';
            app.LayoutDropDown = uidropdown(app.GridLayout5);
            app.LayoutDropDown.Items = {'Stacked', 'Tiled'};
            app.LayoutDropDown.DropDownOpeningFcn = createCallbackFcn(app, @LayoutDropDownOpening, true);
            app.LayoutDropDown.ValueChangedFcn = createCallbackFcn(app, @VarSelectionTreeCheckedNodesChanged, true);
            app.LayoutDropDown.Value = 'Stacked';
            app.HistogramFilterButton = uibutton(app.GridLayout5, 'push');
            app.HistogramFilterButton.ButtonPushedFcn = createCallbackFcn(app, @HistHistogramFilterButtonPushed, true);
            app.HistogramFilterButton.Text = 'Histogram Filter';
            app.DisplayFilteredCheckBox = uicheckbox(app.GridLayout5);
            app.DisplayFilteredCheckBox.ValueChangedFcn = createCallbackFcn(app, @VarSelectionTreeCheckedNodesChanged, true);
            app.DisplayFilteredCheckBox.Text = 'Display Filtered';
            app.XAxesVariableDropDownLabel = uilabel(app.GridLayout5);
            app.XAxesVariableDropDownLabel.Text = 'X Axes Variable';
            app.XAxesVariableDropDown = uidropdown(app.GridLayout5);
            app.XAxesVariableDropDown.Items = {'ProfileDistance', '1:n'};
            app.XAxesVariableDropDown.DropDownOpeningFcn = createCallbackFcn(app, @XAxesVariableDropDownOpening, true);
            app.XAxesVariableDropDown.ValueChangedFcn = createCallbackFcn(app, @XAxesVariableDropDownValueChanged, true);
            app.XAxesVariableDropDown.Value = '1:n';
            app.FilterasNaNCheckBox = uicheckbox(app.GridLayout5);
            app.FilterasNaNCheckBox.ValueChangedFcn = createCallbackFcn(app, @VarSelectionTreeCheckedNodesChanged, true);
            app.FilterasNaNCheckBox.Text = 'Filter as NaN';
            app.FigureFontSizeSpinnerLabel = uilabel(app.GridLayout5);
            app.FigureFontSizeSpinnerLabel.Text = 'Figure FontSize';
            app.FigureFontSizeSpinner = uispinner(app.GridLayout5);
            app.FigureFontSizeSpinner.ValueChangingFcn = createCallbackFcn(app, @VarSelectionTreeCheckedNodesChanged, true);
            app.FigureFontSizeSpinner.Limits = [1 Inf];
            app.FigureFontSizeSpinner.ValueChangedFcn = createCallbackFcn(app, @FigureFontSizeSpinnerValueChanged, true);
            app.FigureFontSizeSpinner.Value = 14;
            app.VarSelectionTree = uitree(app.GridLayout5, 'checkbox');
            % Assign Checked Nodes
            app.VarSelectionTree.CheckedNodesChangedFcn = createCallbackFcn(app, @VarSelectionTreeCheckedNodesChanged, true);
            app.NewEasyPlotButton = uibutton(app.GridLayout5, 'push');
            app.NewEasyPlotButton.ButtonPushedFcn = createCallbackFcn(app, @NewEasyPlotButtonPushed, true);
            app.NewEasyPlotButton.Text = 'New Profile EasyPlot';
            app.AddToEasyPlotButton = uibutton(app.GridLayout5, 'push');
            app.AddToEasyPlotButton.ButtonPushedFcn = createCallbackFcn(app, @AddToEasyPlotButtonPushed, true);
            app.AddToEasyPlotButton.Text = 'Add To Profile EasyPlot';
            app.IndividualEasyPlotButton = uibutton(app.GridLayout5, 'push');
            app.IndividualEasyPlotButton.ButtonPushedFcn = createCallbackFcn(app, @IndividualEasyPlotButtonPushed, true);
            app.IndividualEasyPlotButton.Visible = 'off';
            app.IndividualEasyPlotButton.Text = 'Individual Profile EasyPlot';
            app.DataSubdivisionTab = uitab(app.TabGroup2);
            app.DataSubdivisionTab.Title = 'Data Subdivision';
            app.GridLayout6 = uigridlayout(app.DataSubdivisionTab);
            app.UseDataSubdivisionCheckBox = uicheckbox(app.GridLayout6);
            app.UseDataSubdivisionCheckBox.ValueChangedFcn = createCallbackFcn(app, @UseDataSubdivisionCheckBoxValueChanged, true);
            app.UseDataSubdivisionCheckBox.Text = 'Use Data Subdivision';
            app.ListBox = uilistbox(app.GridLayout6);
            app.ListBox.Items = {};
            app.ListBox.ValueChangedFcn = createCallbackFcn(app, @ListBoxValueChanged, true);
            app.ListBox.Visible = 'off';
            app.ListBox.Value = {};
            app.ListBox_2 = uilistbox(app.GridLayout6);
            app.ListBox_2.Items = {};
            app.ListBox_2.Multiselect = 'on';
            app.ListBox_2.ValueChangedFcn = createCallbackFcn(app, @ListBox_2ValueChanged, true);
            app.ListBox_2.Visible = 'off';
            app.ListBox_2.Value = {};
            app.nEditFieldLabel = uilabel(app.GridLayout6);
            app.nEditFieldLabel.Visible = 'off';
            app.nEditFieldLabel.Text = 'n';
            app.nEditField = uieditfield(app.GridLayout6, 'numeric');
            app.nEditField.Editable = 'off';
            app.nEditField.Visible = 'off';
            app.AppearanceDropDownLabel = uilabel(app.GridLayout6);
            app.AppearanceDropDownLabel.Text = 'Appearance';
            app.AppearanceDropDown = uidropdown(app.GridLayout6);
            app.AppearanceDropDown.Items = {'Color Symbol', 'Symbol Color'};
            app.AppearanceDropDown.Value = 'Color Symbol';
            app.CustomXLabelEditFieldLabel = uilabel(app.GridLayout6);
            app.CustomXLabelEditFieldLabel.Visible = 'off';
            app.CustomXLabelEditFieldLabel.Text = 'Custom XLabel';
            app.CustomXLabelEditField = uieditfield(app.GridLayout6, 'text');
            app.CustomXLabelEditField.Visible = 'off';
            app.CustomYLabelEditFieldLabel = uilabel(app.GridLayout6);
            app.CustomYLabelEditFieldLabel.Visible = 'off';
            app.CustomYLabelEditFieldLabel.Text = 'Custom YLabel';
            app.CustomYLabelEditField = uieditfield(app.GridLayout6, 'text');
            app.CustomYLabelEditField.Visible = 'off';
            app.CustomAxesLabelsCheckBox = uicheckbox(app.GridLayout6);
            app.CustomAxesLabelsCheckBox.ValueChangedFcn = createCallbackFcn(app, @CustomAxesLabelsCheckBoxValueChanged, true);
            app.CustomAxesLabelsCheckBox.Text = 'Custom Axes Labels';
            app.LabelInterpreterDropDownLabel = uilabel(app.GridLayout6);
            app.LabelInterpreterDropDownLabel.Text = 'Label Interpreter';
            app.LabelInterpreterDropDown = uidropdown(app.GridLayout6);
            app.LabelInterpreterDropDown.Items = {'none', 'tex', 'latex'};
            app.LabelInterpreterDropDown.Value = 'none';
            app.FigureFontSizeSpinner_2Label = uilabel(app.GridLayout6);
            app.FigureFontSizeSpinner_2Label.Text = 'Figure FontSize';
            app.FigureFontSizeSpinner_2 = uispinner(app.GridLayout6);
            app.FigureFontSizeSpinner_2.Limits = [1 Inf];
            app.FigureFontSizeSpinner_2.ValueChangedFcn = createCallbackFcn(app, @FigureFontSizeSpinnerValueChanged, true);
            app.FigureFontSizeSpinner_2.Value = 14;
            app.GeoCPlotButton = uibutton(app.GridLayout6, 'push');
            app.GeoCPlotButton.ButtonPushedFcn = createCallbackFcn(app, @GeoCPlotButtonPushed, true);
            app.GeoCPlotButton.Text = 'GeoCPlot';
            app.CustomPlotsTab = uitab(app.TabGroup2);
            app.CustomPlotsTab.Title = 'Custom Plots';
            app.GridLayout8 = uigridlayout(app.CustomPlotsTab);
            app.NewXYEasyPlotButton_4 = uibutton(app.GridLayout8, 'push');
            app.NewXYEasyPlotButton_4.ButtonPushedFcn = createCallbackFcn(app, @NewXYEasyPlotButton_4Pushed, true);
            app.NewXYEasyPlotButton_4.Text = 'New XY EasyPlot';
            app.Panel_2 = uipanel(app.GridLayout8);
            app.Panel_2.Visible = 'off';
            app.GridLayout = uigridlayout(app.Panel_2);
            app.DisplayValuesOutsideData = uicheckbox(app.GridLayout);
            app.DisplayValuesOutsideData.Text = 'Display Values Outside Data Range (No C Limit Update)';
            app.Options_LowerCheckBox = uicheckbox(app.GridLayout);
            app.Options_LowerCheckBox.Tooltip = {'Add a lower color layer'};
            app.Options_LowerCheckBox.Text = 'Lower';
            app.Options_LowerCheckBox.Value = true;
            app.Options_UpperCheckBox = uicheckbox(app.GridLayout);
            app.Options_UpperCheckBox.Tooltip = {'Add an upper color layer'};
            app.Options_UpperCheckBox.Text = 'Upper';
            app.Options_UpperColor = uidropdown(app.GridLayout);
            app.Options_UpperColor.Items = {'Black', 'White'};
            app.Options_UpperColor.Tooltip = {'Select the color for the upper layer'};
            app.Options_UpperColor.Value = 'Black';
            app.Options_ColormapResEditField = uieditfield(app.GridLayout, 'numeric');
            app.Options_ColormapResEditField.Limits = [3 9999];
            app.Options_ColormapResEditField.RoundFractionalValues = 'on';
            app.Options_ColormapResEditField.Tooltip = {'Colormap resolution (def. 256)'};
            app.Options_ColormapResEditField.Value = 256;
            app.Options_LogColormap = uicheckbox(app.GridLayout);
            app.Options_LogColormap.Tooltip = {'Use a log colormap'};
            app.Options_LogColormap.Text = 'Log';
            app.ColormapDropDownLabel = uilabel(app.GridLayout);
            app.ColormapDropDownLabel.Text = 'Colormap';
            app.Options_ColormapDropDown = uidropdown(app.GridLayout);
            app.Options_ColormapDropDown.Tooltip = {'Select the active colormap'};
            app.Options_InvertColormap = uicheckbox(app.GridLayout);
            app.Options_InvertColormap.Text = 'Invert';
            app.Options_LowerColor = uidropdown(app.GridLayout);
            app.Options_LowerColor.Items = {'Black', 'White'};
            app.Options_LowerColor.Tooltip = {'Select the color for the lower layer'};
            app.Options_LowerColor.Value = 'Black';
            app.CustomColormapCheckBox = uicheckbox(app.GridLayout8);
            app.CustomColormapCheckBox.ValueChangedFcn = createCallbackFcn(app, @CustomColormapCheckBoxValueChanged, true);
            app.CustomColormapCheckBox.Text = 'Custom Colormap';
            app.IndividualXYEasyPlotButton = uibutton(app.GridLayout8, 'push');
            app.IndividualXYEasyPlotButton.ButtonPushedFcn = createCallbackFcn(app, @IndividualXYEasyPlotButtonPushed, true);
            app.IndividualXYEasyPlotButton.Visible = 'off';
            app.IndividualXYEasyPlotButton.Text = 'Individual XY EasyPlot';
            app.XAxesVariableDropDown_2Label = uilabel(app.GridLayout8);
            app.XAxesVariableDropDown_2Label.Text = 'X Axes Variable';
            app.XAxesVariableDropDown_2 = uidropdown(app.GridLayout8);
            app.XAxesVariableDropDown_2.Items = {'ProfileDistance', '1:n'};
            app.XAxesVariableDropDown_2.DropDownOpeningFcn = createCallbackFcn(app, @XAxesVariableDropDown_2Opening, true);
            app.XAxesVariableDropDown_2.ValueChangedFcn = createCallbackFcn(app, @XAxesVariableDropDown_2ValueChanged, true);
            app.XAxesVariableDropDown_2.Value = '1:n';
            app.YAxesVariableDropDownLabel = uilabel(app.GridLayout8);
            app.YAxesVariableDropDownLabel.Text = 'Y Axes Variable';
            app.YAxesVariableDropDown = uidropdown(app.GridLayout8);
            app.YAxesVariableDropDown.Items = {'ProfileDistance', '1:n'};
            app.YAxesVariableDropDown.DropDownOpeningFcn = createCallbackFcn(app, @YAxesVariableDropDownOpening, true);
            app.YAxesVariableDropDown.ValueChangedFcn = createCallbackFcn(app, @YAxesVariableDropDownValueChanged, true);
            app.YAxesVariableDropDown.Value = '1:n';
            app.ZAxesVariableDropDownLabel = uilabel(app.GridLayout8);
            app.ZAxesVariableDropDownLabel.Visible = 'off';
            app.ZAxesVariableDropDownLabel.Text = 'Z Axes Variable';
            app.ZAxesVariableDropDown = uidropdown(app.GridLayout8);
            app.ZAxesVariableDropDown.Items = {'ProfileDistance', '1:n'};
            app.ZAxesVariableDropDown.DropDownOpeningFcn = createCallbackFcn(app, @ZAxesVariableDropDownOpening, true);
            app.ZAxesVariableDropDown.ValueChangedFcn = createCallbackFcn(app, @ZAxesVariableDropDownValueChanged, true);
            app.ZAxesVariableDropDown.Visible = 'off';
            app.ZAxesVariableDropDown.Value = '1:n';
            app.CAxesVariableDropDownLabel = uilabel(app.GridLayout8);
            app.CAxesVariableDropDownLabel.Visible = 'off';
            app.CAxesVariableDropDownLabel.Text = 'C Axes Variable';
            app.CAxesVariableDropDown = uidropdown(app.GridLayout8);
            app.CAxesVariableDropDown.Items = {'ProfileDistance', '1:n'};
            app.CAxesVariableDropDown.DropDownOpeningFcn = createCallbackFcn(app, @CAxesVariableDropDownOpening, true);
            app.CAxesVariableDropDown.ValueChangedFcn = createCallbackFcn(app, @CAxesVariableDropDownValueChanged, true);
            app.CAxesVariableDropDown.Visible = 'off';
            app.CAxesVariableDropDown.Value = '1:n';
            app.XLabelEditFieldLabel = uilabel(app.GridLayout8);
            app.XLabelEditFieldLabel.Text = 'XLabel';
            app.XLabelEditField = uieditfield(app.GridLayout8, 'text');
            app.YLabelEditFieldLabel = uilabel(app.GridLayout8);
            app.YLabelEditFieldLabel.Text = 'YLabel';
            app.YLabelEditField = uieditfield(app.GridLayout8, 'text');
            app.ZLabelEditFieldLabel = uilabel(app.GridLayout8);
            app.ZLabelEditFieldLabel.Visible = 'off';
            app.ZLabelEditFieldLabel.Text = 'ZLabel';
            app.ZLabelEditField = uieditfield(app.GridLayout8, 'text');
            app.ZLabelEditField.Visible = 'off';
            app.ColorbarLabelEditFieldLabel = uilabel(app.GridLayout8);
            app.ColorbarLabelEditFieldLabel.Visible = 'off';
            app.ColorbarLabelEditFieldLabel.Text = 'Colorbar Label';
            app.ColorbarLabelEditField = uieditfield(app.GridLayout8, 'text');
            app.ColorbarLabelEditField.Visible = 'off';
            app.PlotTypeLabel = uilabel(app.GridLayout8);
            app.PlotTypeLabel.Text = 'PlotType';
            app.PlotType_DropDown = uidropdown(app.GridLayout8);
            app.PlotType_DropDown.Items = {'XY', 'XYZ', 'Ternary', 'Tetrahedron'};
            app.PlotType_DropDown.ValueChangedFcn = createCallbackFcn(app, @PlotType_DropDownValueChanged, true);
            app.PlotType_DropDown.Value = 'XY';
            app.DLabelEditFieldLabel = uilabel(app.GridLayout8);
            app.DLabelEditFieldLabel.Visible = 'off';
            app.DLabelEditFieldLabel.Text = 'DLabel';
            app.DLabelEditField = uieditfield(app.GridLayout8, 'text');
            app.DLabelEditField.Visible = 'off';
            app.DAxesVariableDropDownLabel = uilabel(app.GridLayout8);
            app.DAxesVariableDropDownLabel.Visible = 'off';
            app.DAxesVariableDropDownLabel.Text = 'D Axes Variable';
            app.DAxesVariableDropDown = uidropdown(app.GridLayout8);
            app.DAxesVariableDropDown.Items = {'ProfileDistance', '1:n'};
            app.DAxesVariableDropDown.DropDownOpeningFcn = createCallbackFcn(app, @DAxesVariableDropDownOpening, true);
            app.DAxesVariableDropDown.ValueChangedFcn = createCallbackFcn(app, @DAxesVariableDropDownValueChanged, true);
            app.DAxesVariableDropDown.Visible = 'off';
            app.DAxesVariableDropDown.Value = '1:n';
            app.LabelInterpreterDropDown_2Label = uilabel(app.GridLayout8);
            app.LabelInterpreterDropDown_2Label.Text = 'Label Interpreter';
            app.LabelInterpreterDropDown_2 = uidropdown(app.GridLayout8);
            app.LabelInterpreterDropDown_2.Items = {'none', 'tex', 'latex'};
            app.LabelInterpreterDropDown_2.Value = 'none';
            app.FigureFontSizeSpinner_3Label = uilabel(app.GridLayout8);
            app.FigureFontSizeSpinner_3Label.Text = 'Figure FontSize';
            app.FigureFontSizeSpinner_3 = uispinner(app.GridLayout8);
            app.FigureFontSizeSpinner_3.Limits = [1 Inf];
            app.FigureFontSizeSpinner_3.ValueChangedFcn = createCallbackFcn(app, @FigureFontSizeSpinnerValueChanged, true);
            app.FigureFontSizeSpinner_3.Value = 14;
            app.XScaleDropDownLabel = uilabel(app.GridLayout8);
            app.XScaleDropDownLabel.Text = 'X Scale';
            app.XScaleDropDown = uidropdown(app.GridLayout8);
            app.XScaleDropDown.Items = {'linear', 'log'};
            app.XScaleDropDown.Value = 'linear';
            app.YScaleDropDown = uidropdown(app.GridLayout8);
            app.YScaleDropDown.Items = {'linear', 'log'};
            app.YScaleDropDown.Value = 'linear';
            app.YScaleDropDownLabel = uilabel(app.GridLayout8);
            app.YScaleDropDownLabel.Text = 'Y Scale';
            app.ZScaleDropDownLabel = uilabel(app.GridLayout8);
            app.ZScaleDropDownLabel.Text = 'Z Scale';
            app.ZScaleDropDown = uidropdown(app.GridLayout8);
            app.ZScaleDropDown.Items = {'linear', 'log'};
            app.ZScaleDropDown.Value = 'linear';
            app.CScaleDropDownLabel = uilabel(app.GridLayout8);
            app.CScaleDropDownLabel.Text = 'C Scale';
            app.CScaleDropDown = uidropdown(app.GridLayout8);
            app.CScaleDropDown.Items = {'linear', 'log'};
            app.CScaleDropDown.Value = 'linear';
            app.TraceElementsTab = uitab(app.TabGroup2);
            app.TraceElementsTab.Title = 'TraceElements';
            app.GridLayout9 = uigridlayout(app.TraceElementsTab);
            app.UIAxes = uiaxes(app.GridLayout9);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.Visible = 'off';
            app.NormTEPlotButton = uibutton(app.GridLayout9, 'push');
            app.NormTEPlotButton.ButtonPushedFcn = createCallbackFcn(app, @NormTEPlotButtonPushed, true);
            app.NormTEPlotButton.Text = 'NormTEPlot';
            app.CustomNormTraceElementPlotButton = uibutton(app.GridLayout9, 'push');
            app.CustomNormTraceElementPlotButton.ButtonPushedFcn = createCallbackFcn(app, @CustomNormTraceElementPlotButtonPushed, true);
            app.CustomNormTraceElementPlotButton.Visible = 'off';
            app.CustomNormTraceElementPlotButton.Text = 'Custom Norm Trace Element Plot';
            app.ListBox_3 = uilistbox(app.GridLayout9);
            app.ListBox_3.Items = {};
            app.ListBox_3.ValueChangedFcn = createCallbackFcn(app, @ListBox_3ValueChanged, true);
            app.ListBox_3.Visible = 'off';
            app.ListBox_3.Value = {};
            app.ListBox_4 = uilistbox(app.GridLayout9);
            app.ListBox_4.Items = {};
            app.ListBox_4.ValueChangedFcn = createCallbackFcn(app, @ListBox_4ValueChanged, true);
            app.ListBox_4.Visible = 'off';
            app.ListBox_4.Value = {};
            app.UserNormCheckBox = uicheckbox(app.GridLayout9);
            app.UserNormCheckBox.ValueChangedFcn = createCallbackFcn(app, @UserNormCheckBoxValueChanged, true);
            app.UserNormCheckBox.Text = 'UserNorm';
            app.FilterElementsCheckBox = uicheckbox(app.GridLayout9);
            app.FilterElementsCheckBox.ValueChangedFcn = createCallbackFcn(app, @FilterElementsCheckBoxValueChanged, true);
            app.FilterElementsCheckBox.Text = 'Filter  Elements';
            app.PlotSelectedButton = uibutton(app.GridLayout9, 'push');
            app.PlotSelectedButton.ButtonPushedFcn = createCallbackFcn(app, @PlotSelectedButtonPushed, true);
            app.PlotSelectedButton.Visible = 'off';
            app.PlotSelectedButton.Text = 'Plot Selected';
            app.NormToDropDownLabel = uilabel(app.GridLayout9);
            app.NormToDropDownLabel.Text = 'Norm To';
            app.NormToDropDown = uidropdown(app.GridLayout9);
            app.NormToDropDown.Items = {};
            app.NormToDropDown.ValueChangedFcn = createCallbackFcn(app, @NormToDropDownValueChanged, true);
            app.NormToDropDown.Value = {};
            app.ElementstoPlotListBox = uilistbox(app.GridLayout9);
            app.ElementstoPlotListBox.Items = {};
            app.ElementstoPlotListBox.Multiselect = 'on';
            app.ElementstoPlotListBox.Visible = 'off';
            app.ElementstoPlotListBox.Value = {};
            app.DefaultsDropDownLabel = uilabel(app.GridLayout9);
            app.DefaultsDropDownLabel.Visible = 'off';
            app.DefaultsDropDownLabel.Text = 'Defaults';
            app.DefaultsDropDown = uidropdown(app.GridLayout9);
            app.DefaultsDropDown.Items = {'Element data in table', 'All Elements', 'REE (Ionic Radii)', 'REE', 'Trace Elements', 'Spider', 'Elements in table', 'Masses in table', 'Mass data in table', 'Mass data in table ~0'};
            app.DefaultsDropDown.ValueChangedFcn = createCallbackFcn(app, @DefaultsDropDownValueChanged, true);
            app.DefaultsDropDown.Visible = 'off';
            app.DefaultsDropDown.Value = 'Element data in table';
            app.FigureFontSizeSpinner_4Label = uilabel(app.GridLayout9);
            app.FigureFontSizeSpinner_4Label.Text = 'Figure FontSize';
            app.FigureFontSizeSpinner_4 = uispinner(app.GridLayout9);
            app.FigureFontSizeSpinner_4.Limits = [1 Inf];
            app.FigureFontSizeSpinner_4.ValueChangedFcn = createCallbackFcn(app, @FigureFontSizeSpinnerValueChanged, true);
            app.FigureFontSizeSpinner_4.Value = 14;
            app.SaveNormDataButton = uibutton(app.GridLayout9, 'push');
            app.SaveNormDataButton.ButtonPushedFcn = createCallbackFcn(app, @SaveNormDataButtonPushed, true);
            app.SaveNormDataButton.Text = 'Save Norm Data';
            app.SigDigitsSpinnerLabel = uilabel(app.GridLayout9);
            app.SigDigitsSpinnerLabel.Text = 'Sig. Digits';
            app.SigDigitsSpinner = uispinner(app.GridLayout9);
            app.SigDigitsSpinner.Limits = [0 16];
            app.SigDigitsSpinner.RoundFractionalValues = 'on';
            app.SigDigitsSpinner.Value = 3;
            app.Hyperlink = uihyperlink(app.GridLayout3);
            app.Hyperlink.URL = 'https://github.com/NilsGies/MinPlotX';
            app.Hyperlink.Visible = 'off';
            app.Hyperlink.Text = 'This is a MinPlotX module! Find out more about MinPlotX here';
            app.MinPlotXLogo = uiimage(app.GridLayout3);
            app.MinPlotXLogo.Visible = 'off';
            app.MinPlotXLogo.ImageSource = 'MinPlotX_logo.png';
            app.DataTable_2 = uitab(app.TabGroup);
            app.DataTable_2.Title = 'DataTable';
            app.GridLayout7 = uigridlayout(app.DataTable_2);
            app.UITable = uitable(app.GridLayout7);
            app.UITable.ColumnName = '';
            app.UITable.RowName = {};
            app.SpotVisModuleButton = uibutton(app.GridLayout7, 'push');
            app.SpotVisModuleButton.ButtonPushedFcn = createCallbackFcn(app, @SpotVisModuleButtonPushed, true);
            app.SpotVisModuleButton.Text = 'SpotVisModule';
            app.ResetMask = uibutton(app.GridLayout7, 'push');
            app.ResetMask.ButtonPushedFcn = createCallbackFcn(app, @ResetMaskPushed, true);
            app.ResetMask.Icon = 'symbols_1_Clear Mask.png';
            app.ResetMask.Tooltip = {'Reset filter'};
            app.ResetMask.Text = {''; ''};
            app.InvertMask = uibutton(app.GridLayout7, 'push');
            app.InvertMask.ButtonPushedFcn = createCallbackFcn(app, @InvertMaskPushed, true);
            app.InvertMask.Icon = 'symbols_1_Invert Mask.png';
            app.InvertMask.Tooltip = {'Invert filter'};
            app.InvertMask.Text = '';
            app.Add2Mask = uibutton(app.GridLayout7, 'push');
            app.Add2Mask.ButtonPushedFcn = createCallbackFcn(app, @Add2MaskPushed, true);
            app.Add2Mask.Icon = 'symbols_1_Mask Selection.png';
            app.Add2Mask.Tooltip = {'Activate selected rows'};
            app.Add2Mask.Text = '';
            app.RemoveFromMask = uibutton(app.GridLayout7, 'push');
            app.RemoveFromMask.ButtonPushedFcn = createCallbackFcn(app, @RemoveFromMaskPushed, true);
            app.RemoveFromMask.Icon = 'symbols_1_Substract from Mask.png';
            app.RemoveFromMask.Tooltip = {'Deactivate selected rows'};
            app.RemoveFromMask.Text = '';
            app.ContextMenu = uicontextmenu(app.VisModule_GUI);
            app.CheckallMenu = uimenu(app.ContextMenu);
            app.CheckallMenu.MenuSelectedFcn = createCallbackFcn(app, @CheckallMenuSelected, true);
            app.CheckallMenu.Text = 'Check all';
            app.UncheckallMenu = uimenu(app.ContextMenu);
            app.UncheckallMenu.MenuSelectedFcn = createCallbackFcn(app, @UncheckallMenuSelected, true);
            app.UncheckallMenu.Text = 'Uncheck all';
            % Assign app.ContextMenu
            app.Tree_2.ContextMenu = app.ContextMenu;
            app.VarSelectionTree.ContextMenu = app.ContextMenu;
            % Show the figure after all components are created
            app.VisModule_GUI.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = VisModule(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.VisModule_GUI)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.VisModule_GUI)
        end
    end
end