classdef Quantification_Module < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        BaseMenu                      matlab.ui.container.Menu
        SaveSessiontoBaseandExitMenu  matlab.ui.container.Menu
        ShowDwelltimesButton          matlab.ui.control.Button
        ProgressPanel                 matlab.ui.container.Panel
        ResultsButton                 matlab.ui.control.Button
        QuantificationButton          matlab.ui.control.Button
        DriftButton                   matlab.ui.control.Button
        ClassificationButton          matlab.ui.control.Button
        ImportButton                  matlab.ui.control.Button
        IntervalButton                matlab.ui.control.Button
        BracketIdDropDown             matlab.ui.control.DropDown
        BracketIdDropDownLabel        matlab.ui.control.Label
        PrimaryReferenceMaterialDropDown  matlab.ui.control.DropDown
        PrimaryReferenceMaterialDropDownLabel  matlab.ui.control.Label
        InclusionTable                matlab.ui.control.Table
        Panel                         matlab.ui.container.Panel
        ChooseButton                  matlab.ui.control.Button
        StringCompareDropDown         matlab.ui.control.DropDown
        StringCompareInputEditField   matlab.ui.control.NumericEditField
        SpotnamecontainsEditField     matlab.ui.control.EditField
        ContainsLabel                 matlab.ui.control.Label
        ApplyButton                   matlab.ui.control.Button
        ProceedtoResultsModuleButton  matlab.ui.control.Button
        fromPRMListBox                matlab.ui.control.ListBox
        AnalyteListBox                matlab.ui.control.ListBox
        ResetButton                   matlab.ui.control.Button
        MatrixTable                   matlab.ui.control.Table
    end


    properties (Access = public)
        base
        project_id
        session_id
    end


    properties (Access = private)
        sourceapp

        selected_PRM
        selected_bracket_id

        selected_spot
        selected_spot_inclusion
        selected_table

        %%%%%%%%% needed for stringcmp spotname search
        all_white_cells
        %%%%%%%%%%


        %%%% for quantification %%%%
        bg_corrected_cps_matrix
        sensitivity_relative_to_internal_standard
        bg_corrected_cps_inclusion
        sensitivity_relative_to_internal_standard_inclusion

        %%% for total oxides %%%%
        Fe2_3

        editstatus = false % once this is true it asks if the data should be saved

    end

    methods (Access = private)

        function DisplayErrorNG(app,ME,WhichOperation)
            disp(' ')
            disp('%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%')
            disp('%%%%%%%%%%%%%% Error %%%%%%%%%%%%%%%%')
            disp('%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%')
            disp(['Error occured during: ' WhichOperation])
            % for n=1
            for n=numel(ME.stack):-1:1
                disp('Error in file:')
                disp(ME.stack(n).file)
                disp(['Error in line: ',num2str(ME.stack(n).line)])
            end
            disp(['Error in messsage: ' ME.message])
            disp(' ')
            disp('%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%')
            disp(' ')

        end

        function update_progress_buttons(app)

            status = app.base.project.session.meta.module_status;

            green = [204/255, 1, 204/255];
            orange = [1, 229/255, 204/255];
            red = [1, 204/255, 204/255];

            if isfield(status, 'import') && status.import == true
                app.ImportButton.Enable = "off";
                app.ImportButton.BackgroundColor = green;
            else
                app.ImportButton.Enable = "off";
                app.ImportButton.BackgroundColor = red;
            end

            if isfield(status, 'interval') && status.interval == true
                app.IntervalButton.Enable = "on";
                app.IntervalButton.BackgroundColor = green;
            else
                app.IntervalButton.Enable = "off";
                app.IntervalButton.BackgroundColor = red;
            end

            if isfield(status, 'classification') && status.classification == true
                app.ClassificationButton.Enable = "on";
                app.ClassificationButton.BackgroundColor = green;
            else
                app.ClassificationButton.Enable = "off";
                app.ClassificationButton.BackgroundColor = red;
            end

            if isfield(status, 'drift') && status.drift == true
                app.DriftButton.Enable = "on";
                app.DriftButton.BackgroundColor = green;
            else
                app.DriftButton.Enable = "off";
                app.DriftButton.BackgroundColor = red;
            end

            app.QuantificationButton.Enable = "on";
            app.QuantificationButton.BackgroundColor = orange;
            app.QuantificationButton.FontWeight = 'bold';

            if isfield(status, 'results') && status.results == true
                app.ResultsButton.Enable = "on";
                app.ResultsButton.BackgroundColor = green;
            else
                app.ResultsButton.Enable = "off";
                app.ResultsButton.BackgroundColor = red;
            end

        end

        function changes = check_changes(app)

            status = app.base.project.session.meta.module_status;

            if  status.results

                % uiconfirm ask user if finished
                fig = app.UIFigure;
                msg = "You changed something, do you want to reset the results?";
                title = "Attention";
                selection = uiconfirm(fig,msg,title, ...
                    "Options",["Yes","Cancel"], ...
                    "DefaultOption",1,"CancelOption",2);
                switch selection
                    case 'Yes'
                        app.base.project.session.meta.module_status.results = false;

                        % delete all variables that depend on this

                        if isfield(app.base.project.session.spot, 'matrix_composition')
                            app.base.project.session.spot = rmfield(app.base.project.session.spot, 'matrix_composition');
                        end
                        if isfield(app.base.project.session.spot, 'matrix_limits_of_detection')
                            app.base.project.session.spot = rmfield(app.base.project.session.spot, 'matrix_limits_of_detection');
                        end

                        if isequal(app.base.project.session.meta.reductiontype, 'Inclusion')

                            if isfield(app.base.project.session.spot, 'inclusion_composition')
                                app.base.project.session.spot = rmfield(app.base.project.session.spot, 'inclusion_composition');
                            end
                            if isfield(app.base.project.session.spot, 'inclusion_limits_of_detection')
                                app.base.project.session.spot = rmfield(app.base.project.session.spot, 'inclusion_limits_of_detection');
                            end

                        end

                        [app.project_id, app.session_id] = save_to_base(app.sourceapp, app.project_id, app.base.project.session, app.session_id, app.base.project_name, settings);

                        changes = true;

                        update_progress_buttons(app)

                    case 'Cancel'
                        update_quantification_module_GUI(app)
                        changes = false;
                        return
                end
            else
                changes = true;
            end
        end

        function updateDwellTime(app, src, event)
            % Get edited value
            %newValue = event.EditData;
            newValue = str2double(event.EditData);
            % Validate input is numeric and positive
            if isnumeric(newValue) && newValue > 0
                % Update the table in memory
                app.base.project.session.dwelltimes{1, event.Indices(2)} = newValue;
            else
                % Revert invalid changes
                src.Data(event.Indices(1), event.Indices(2)) = event.PreviousData;
            end

            changes = check_changes(app);
            if changes == false
                return
            end
            app.editstatus = true;

        end


        %%%%%%%%%%%%%%%%%%%%%%%%%% GUI %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        function update_quantification_module_GUI(app)

            % Get indices where type is "PRM"
            prm_indices = strcmp(app.base.project.session.spot.type, "PRM");
            % Get reference materials only for PRM spots
            prm_materials = app.base.project.session.spot.referencematerial(prm_indices);
            % Get unique names, excluding "None" and "Select Reference Material"
            unique_names = unique(prm_materials);
            unique_names = unique_names(~strcmp(unique_names, 'None'));
            validPRMs = unique_names(~strcmp(unique_names, 'Select Reference Material'));

            % Populate PRM dropdown
            app.PrimaryReferenceMaterialDropDown.Items = validPRMs;
            app.PrimaryReferenceMaterialDropDown.ItemsData = 1:numel(app.PrimaryReferenceMaterialDropDown.Items);

            % Populate stringcompare dropdown
            app.StringCompareDropDown.Items = ["µg/g", "% Total Oxides", "FeO/(FeO + Fe2O3)"];
            app.StringCompareDropDown.ItemsData = app.StringCompareDropDown.Items;

            % Get selected reference material and find matching indices
            selectedRM = validPRMs(app.PrimaryReferenceMaterialDropDown.Value);
            rmIndices = strcmp(app.base.project.session.spot.referencematerial, selectedRM);

            % Filter bracket IDs for selected reference material
            filteredBracketIds = app.base.project.session.spot.bracket_id(rmIndices);
            uniqueBracketIds = unique(filteredBracketIds);
            uniqueBracketIds = uniqueBracketIds(uniqueBracketIds ~= 0);

            % Update bracket ID dropdown
            app.BracketIdDropDown.Items = string(uniqueBracketIds);
            app.BracketIdDropDown.ItemsData = uniqueBracketIds;

            % at startup the selected_PRM is empty, make it 1 so that
            % something is plotted anyways
            if isempty(app.selected_PRM)
                app.selected_PRM = 1;
            end
            % Set default bracket_id if empty - use first available bracket_id
            if isempty(app.selected_bracket_id)
                app.selected_bracket_id = uniqueBracketIds(1);
                app.BracketIdDropDown.Value = uniqueBracketIds(1);
            end

            %%%%%%%%%%%%%%%%%%%%
            update_matrix_table(app)
            update_matrix_table_style(app)

            if isequal(app.base.project.session.meta.reductiontype, 'Inclusion')
                app.InclusionTable.Visible = 'on';
                update_inclusion_table(app)
                update_inclusion_table_style(app)
            end

            if strcmp(app.selected_table, 'InclusionTable')
                %Get selected column index and name of InclusionTable
                if ~isempty(app.InclusionTable.Selection)
                    selectedCol = unique(app.InclusionTable.Selection(:, 2));
                    selectedRows = unique(app.InclusionTable.Selection(:, 1));
                    colName = app.InclusionTable.ColumnName{selectedCol};
                else
                    return
                end
            elseif strcmp(app.selected_table, 'MatrixTable')
                %Get selected column index and name of Matrix Table
                if ~isempty(app.MatrixTable.Selection)
                    selectedCol = unique(app.MatrixTable.Selection(:, 2));
                    selectedRows = unique(app.MatrixTable.Selection(:, 1));
                    colName = app.MatrixTable.ColumnName{selectedCol};
                else
                    return
                end
            end

            if ~exist('selectedRows') %#ok
                selectedRows = 1;
            end
            if ~exist('colName') %#ok
                colName = 'Analyte';
            end

            %%%%%%%%%%%%%%%%%%%%

            if ~isempty(app.selected_PRM)
                % First check if selected cell has zero sensitivities
                sens_data = table2array(app.base.project.session.spot.sensitivities{app.BracketIdDropDown.Value, app.selected_PRM});
                selected_row_sums = sum(sens_data(selectedRows, :), 2);
            else
                return
            end

            % If selected cell has zero sensitivities, hide both listboxes and return
            if any(selected_row_sums == 0)
                app.AnalyteListBox.Visible = 'off';
                app.fromPRMListBox.Visible = 'off';
                return;
            end

            if contains(colName, 'Analyte') && strcmp(app.selected_table, 'MatrixTable')

                % Get analytes for this PRM from sensitivities
                if ~isempty(app.selected_PRM)
                    % Update ListBox
                    app.AnalyteListBox.Visible = 'on';
                    app.AnalyteListBox.Items = ['=================='; 'Total Oxides'; app.base.project.session.spot.sensitivities{app.BracketIdDropDown.Value, app.selected_PRM}.Properties.VariableNames'];
                    app.AnalyteListBox.ItemsData = app.AnalyteListBox.Items;
                    app.fromPRMListBox.Visible = 'off';
                end

            elseif contains(colName, 'Analyte') && strcmp(app.selected_table, 'InclusionTable')

                % Get analytes for this PRM from sensitivities
                if ~isempty(app.selected_PRM)
                    % Update ListBox
                    app.AnalyteListBox.Visible = 'on';
                    app.AnalyteListBox.Items = ['=================='; app.base.project.session.spot.sensitivities{app.BracketIdDropDown.Value, app.selected_PRM}.Properties.VariableNames'];
                    app.AnalyteListBox.ItemsData = app.AnalyteListBox.Items;
                    app.fromPRMListBox.Visible = 'off';
                end

            elseif contains(colName, 'µg/g')

                % Hide Analyte ListBox
                app.AnalyteListBox.Visible = 'off';

                % Get available PRMs for all selected rows
                availablePRMs = [];
                for prm = 1:numel(validPRMs)
                    % Skip current PRM
                    if strcmp(validPRMs{prm}, validPRMs(app.selected_PRM))
                        continue;
                    end

                    % Check if sensitivity data exists and is valid
                    sens = app.base.project.session.spot.sensitivities{app.BracketIdDropDown.Value, prm};
                    if ~isempty(sens) && ~isequal(sens, 0) && istable(sens)
                        % Check if selected rows have non-zero sensitivities
                        sens_data = table2array(sens);
                        row_sums = sum(sens_data(selectedRows, :), 2);
                        if all(row_sums ~= 0)
                            availablePRMs = [availablePRMs; validPRMs(prm)];
                        end
                    end
                end

                % Only show and populate fromPRMListBox if there are available PRMs
                if ~isempty(availablePRMs)
                    app.fromPRMListBox.Visible = 'on';
                    otherPRMs = "from " + availablePRMs;
                    app.fromPRMListBox.Items = ['=================='; otherPRMs];
                else
                    app.fromPRMListBox.Visible = 'off';
                end
            end

        end

        function update_matrix_table_style(app)
            % Get dimensions and basic data
            newData = '----------------';

            % Initialize style matrices
            all_grey_cells = [];
            app.all_white_cells = [];

            % Find zero and nonzero rows based on sensitivities
            sens_data = table2array(app.base.project.session.spot.sensitivities{app.BracketIdDropDown.Value, app.selected_PRM});
            zero_rows = find(sum(sens_data, 2) == 0);
            nonzero_rows = find(sum(sens_data, 2) ~= 0);

            % Get Total Oxides rows
            total_oxide_rows = find(strcmp(app.base.project.session.spot.internal_standard{app.BracketIdDropDown.Value, app.selected_PRM}, 'Total Oxides'));
            non_total_oxide_rows = setdiff(1:height(app.MatrixTable.Data), total_oxide_rows);

            % Handle zero sensitivity rows
            if ~isempty(zero_rows)
                % Regular columns
                grey_analyte = [zero_rows, repmat(3, length(zero_rows), 1)];
                grey_mass = [zero_rows, repmat(4, length(zero_rows), 1)];
                all_grey_cells = [all_grey_cells; grey_analyte; grey_mass];

                % Update data with dashes
                app.MatrixTable.Data.(3)(zero_rows) = {newData};
                app.MatrixTable.Data.(4)(zero_rows) = {newData};
            end

            % Handle nonzero sensitivity rows
            if ~isempty(nonzero_rows)
                % Regular columns
                white_analyte = [nonzero_rows, repmat(3, length(nonzero_rows), 1)];
                white_mass = [nonzero_rows, repmat(4, length(nonzero_rows), 1)];
                app.all_white_cells = [app.all_white_cells; white_analyte; white_mass];
            end

            % Handle standards
            standards = find(strcmp(app.base.project.session.spot.type, "PRM"));

            standards_style = [];
            % Apply grey style to standards for all columns except first two
            for col = 3:4
                standards_style = [standards_style; [standards, repmat(col, length(standards), 1)]];
                % Apply dashes to standards rows
                app.MatrixTable.Data.(col)(standards) = {newData};
            end

            % Grey out µg/g for Total Oxides rows
            if ~isempty(total_oxide_rows)
                grey_mass_total_oxide = [total_oxide_rows, repmat(4, length(total_oxide_rows), 1)];
                all_grey_cells = [all_grey_cells; grey_mass_total_oxide];
                app.MatrixTable.Data.(4)(total_oxide_rows) = {newData};
            end

            % Grey out wt% and FeO/(FeO + Fe2O3) for non-Total Oxides rows
            if ~isempty(non_total_oxide_rows)
                % Ensure non_total_oxide_rows is a column vector
                non_total_oxide_rows = non_total_oxide_rows(:);

                % Create indices for wt% column (5) and FeO/(FeO + Fe2O3) column (6)
                grey_wt = [non_total_oxide_rows, ones(length(non_total_oxide_rows), 1) * 5];
                grey_fe = [non_total_oxide_rows, ones(length(non_total_oxide_rows), 1) * 6];

                all_grey_cells = [all_grey_cells; grey_wt; grey_fe];
                app.MatrixTable.Data.(5)(non_total_oxide_rows) = {newData};
                app.MatrixTable.Data.(6)(non_total_oxide_rows) = {newData};
            end

            % Apply styles in batch
            removeStyle(app.MatrixTable);
            s_grey = uistyle('BackgroundColor', [0.8, 0.8, 0.8]);
            s_white = uistyle('BackgroundColor', [1, 1, 1]);

            % Apply styles
            if ~isempty(app.all_white_cells)
                addStyle(app.MatrixTable, s_white, 'cell', app.all_white_cells);
            end
            if ~isempty(all_grey_cells) || ~isempty(standards_style)
                addStyle(app.MatrixTable, s_grey, 'cell', [all_grey_cells; standards_style]);
            end

            % Create a centered style for any cells that don't get grey or white background
            s_center = uistyle('HorizontalAlignment', 'center');

            % First apply center alignment to all cells
            addStyle(app.MatrixTable, s_center);

        end

        function update_matrix_table(app)

            % Get basic data
            spotnames = app.base.project.session.spotname(:);
            spotNumbers = (1:numel(spotnames))';

            % Get data for current PRM
            internal_std = app.base.project.session.spot.internal_standard{app.BracketIdDropDown.Value, app.selected_PRM};
            mass_fraction = app.base.project.session.spot.internal_standard_mass_fraction{app.BracketIdDropDown.Value, app.selected_PRM};
            oxide_fraction = app.base.project.session.spot.internal_standard_oxide_wt{app.BracketIdDropDown.Value, app.selected_PRM};
            fe_ratio = app.base.project.session.spot.Fe_ratio_for_total_oxide{app.BracketIdDropDown.Value, app.selected_PRM};

            % Initialize data cell array with correct dimensions
            data = [num2cell(spotNumbers), spotnames, internal_std, mass_fraction, oxide_fraction, fe_ratio];
            colNames = {'#', 'Spotname', 'Analyte', '[µg/g]', '% Total Oxides', 'FeO/(FeO + Fe2O3)'};

            % Create and update table
            T = cell2table(data, 'VariableNames', colNames);
            app.MatrixTable.Data = T;
            app.MatrixTable.ColumnName = colNames;

        end

        function update_inclusion_table(app)

            % Get basic data
            spotnames = app.base.project.session.spotname(:);
            spotNumbers = (1:numel(spotnames))';

            % Get data for current PRM
            internal_std_inclusion = app.base.project.session.spot.internal_standard_inclusion{app.BracketIdDropDown.Value, app.selected_PRM};
            mass_fraction_inclusion = app.base.project.session.spot.internal_standard_mass_fraction_inclusion{app.BracketIdDropDown.Value, app.selected_PRM};

            % Initialize data cell array with correct dimensions
            data = [num2cell(spotNumbers), spotnames, internal_std_inclusion, mass_fraction_inclusion];
            colNames = {'#', 'Spotname','Analyte', '[µg/g]'};

            % Create and update table
            T = cell2table(data, 'VariableNames', colNames);
            app.InclusionTable.Data = T;
            app.InclusionTable.ColumnName = colNames;

        end

        function update_inclusion_table_style(app)
            % Get dimensions and basic data
            newData = '----------------';

            % Initialize style matrices
            all_grey_cells = [];
            app.all_white_cells = [];

            % Find zero and nonzero rows based on sensitivities
            sens_data = table2array(app.base.project.session.spot.sensitivities{app.BracketIdDropDown.Value, app.selected_PRM});
            zero_rows = find(sum(sens_data, 2) == 0);
            nonzero_rows = find(sum(sens_data, 2) ~= 0);

            % Handle zero sensitivity rows
            if ~isempty(zero_rows)
                % Regular columns
                grey_analyte = [zero_rows, repmat(3, length(zero_rows), 1)];
                grey_mass = [zero_rows, repmat(4, length(zero_rows), 1)];
                all_grey_cells = [all_grey_cells; grey_analyte; grey_mass];

                % Update data with dashes
                app.InclusionTable.Data.(3)(zero_rows) = {newData};
                app.InclusionTable.Data.(4)(zero_rows) = {newData};
            end

            % Apply styles in batch
            removeStyle(app.InclusionTable);
            % Handle nonzero sensitivity rows

            if ~isempty(nonzero_rows)
                % Regular columns
                white_analyte = [nonzero_rows, repmat(3, length(nonzero_rows), 1)];
                white_mass = [nonzero_rows, repmat(4, length(nonzero_rows), 1)];
                app.all_white_cells = [app.all_white_cells; white_analyte; white_mass];

            end

            % Handle standards
            standards = find(strcmp(app.base.project.session.spot.type, "PRM"));
            standards_style = [];
            % Apply grey style to standards for all columns except first two
            for col = 3:4
                standards_style = [standards_style; [standards, repmat(col, length(standards), 1)]];
                % Apply dashes to standards rows
                app.InclusionTable.Data.(col)(standards) = {newData};
            end

            s_grey = uistyle('BackgroundColor', [0.8, 0.8, 0.8]);
            s_white = uistyle('BackgroundColor', [1, 1, 1]);

            % Apply styles
            if ~isempty(app.all_white_cells)
                addStyle(app.InclusionTable, s_white, 'cell', app.all_white_cells);
            end
            if ~isempty(all_grey_cells) || ~isempty(standards_style)
                addStyle(app.InclusionTable, s_grey, 'cell', [all_grey_cells; standards_style]);
            end

            % Create a centered style for any cells that don't get grey or white background
            s_center = uistyle('HorizontalAlignment', 'center');

            % First apply center alignment to all cells
            addStyle(app.InclusionTable, s_center);

        end

        %%%%%%%%%%%%%%%% Mass Fraction Calculation %%%%%%%%%%%%%%%%%%%%%
        % these are basically the same functions twice every time, once 
        % for matrix and once for inclusion.
        % There is probably a smoother way to do this but ¯\_(ツ)_/¯ 

        % 1.
        function calculate_background_corrected_matrix_intensities(app)

            % Find all Unknown spots
            all_UNK = find(~strcmp(app.base.project.session.spot.type, "PRM"));

            background_start = app.base.project.session.spot.background_starts(all_UNK);
            background_end = app.base.project.session.spot.background_ends(all_UNK);

            % calculate the mean for the matrix
            matrix_1_start = app.base.project.session.spot.matrix_1_starts(all_UNK);
            matrix_1_end = app.base.project.session.spot.matrix_1_ends(all_UNK);

            matrix_2_start = app.base.project.session.spot.matrix_2_starts(all_UNK);
            matrix_2_end = app.base.project.session.spot.matrix_2_ends(all_UNK);

            matrix_3_start = app.base.project.session.spot.matrix_3_starts(all_UNK);
            matrix_3_end = app.base.project.session.spot.matrix_3_ends(all_UNK);

            % case of full measurement input
            if isequal(app.base.project.session.meta.datatype, 'Continuous')
                signal = app.base.project.session.ms_data;
            end

            % Initialize bg_corrected_cps_matrix as numeric array
            bg_corr_matrix = zeros(length(app.base.project.session.spot.signal_starts), numel(app.base.project.session.analytes));

            for unk = 1:numel(all_UNK)

                % case of single_spot input
                if isequal(app.base.project.session.meta.datatype, 'Single')
                    signal = app.base.project.session.spot.ms_data_single_spot{all_UNK(unk)};
                end

                % calculate the average of the background of every UNK
                mean_analytes_cps_background = mean(signal(background_start{unk}:background_end{unk}, 2:end), 1);

                % Calculate means for each matrix interval, handling empty cases
                if ~isempty(matrix_1_start{unk}) && ~isempty(matrix_1_end{unk})

                    matrix_1_data = signal(matrix_1_start{unk}:matrix_1_end{unk}, 2:end);

                    matrix_1_data_corrected = matrix_1_data - mean_analytes_cps_background;

                    % Replace negative values with 1
                    matrix_1_data_corrected = max(matrix_1_data_corrected, 0);

                    mean_matrix_1 = mean(matrix_1_data_corrected, 1);

                    weights(1) = matrix_1_end{unk} - matrix_1_start{unk} + 1;
                else
                    mean_matrix_1 = array2table(zeros(1, size(signal, 2)-1));
                    weights(1) = 0;
                end

                if ~isempty(matrix_2_start{unk}) && ~isempty(matrix_2_end{unk})

                    matrix_2_data = signal(matrix_2_start{unk}:matrix_2_end{unk}, 2:end);

                    matrix_2_data_corrected = matrix_2_data - mean_analytes_cps_background;

                    % Replace negative values with 1
                    matrix_2_data_corrected = max(matrix_2_data_corrected, 0);

                    mean_matrix_2 = mean(matrix_2_data_corrected, 1);

                    weights(2) = matrix_2_end{unk} - matrix_2_start{unk} + 1;
                else
                    mean_matrix_2 = array2table(zeros(1, size(signal, 2)-1));
                    weights(2) = 0;
                end

                if ~isempty(matrix_3_start{unk}) && ~isempty(matrix_3_end{unk})

                    matrix_3_data = signal(matrix_3_start{unk}:matrix_3_end{unk}, 2:end);

                    matrix_3_data_corrected = matrix_3_data - mean_analytes_cps_background;

                    % Replace negative values with 1
                    matrix_3_data_corrected = max(matrix_3_data_corrected, 0);

                    mean_matrix_3 = mean(matrix_3_data_corrected, 1);

                    weights(3) = matrix_3_end{unk} - matrix_3_start{unk} + 1;
                else
                    mean_matrix_3 = array2table(zeros(1, size(signal, 2)-1));
                    weights(3) = 0;
                end

                % Calculate weighted mean
                total_weight = sum(weights);
                if total_weight > 0
                    mean_analytes_cps_matrix = (table2array(mean_matrix_1) * weights(1) + table2array(mean_matrix_2) * weights(2) + table2array(mean_matrix_3) * weights(3)) / total_weight;
                else
                    continue
                end
                % do the background correction
                bg_corr_matrix(all_UNK(unk), :) = mean_analytes_cps_matrix;
                % Convert to table again
                app.bg_corrected_cps_matrix =  array2table(bg_corr_matrix, 'VariableNames', signal.Properties.VariableNames(2:end));


                % this maybe needs to be changed to operate sweep-wise and
                % not the averages. so first average the background and
                % then subtract this value from every sweep in the matrix
                % intervals. Then average the matrix intervals with weights
                % and these are the bg_corrected_cps


            end
        end

        function calculate_background_corrected_inclusion_intensities(app)
            if isequal(app.base.project.session.meta.reductiontype, 'Inclusion')
                all_UNK = find(~strcmp(app.base.project.session.spot.type, "PRM"));

                % case of full measurement input
                if isequal(app.base.project.session.meta.datatype, 'Continuous')
                    signal = app.base.project.session.ms_data;
                end

                inclusion_means = zeros(length(app.base.project.session.spot.signal_starts), numel(app.base.project.session.analytes));

                for i = 1:numel(all_UNK)
                    unk = all_UNK(i);
                    % case of single_spot input
                    if isequal(app.base.project.session.meta.datatype, 'Single')
                        signal = app.base.project.session.spot.ms_data_single_spot{unk};
                    end

                    if ~isempty(app.base.project.session.spot.inclusion_contribution_to_the_mix{unk})

                        % Get the table
                        contribution_table = app.base.project.session.spot.inclusion_contribution_to_the_mix{unk};

                        % Convert table to array, replace negatives with 0
                        numeric_data = table2array(contribution_table);
                        numeric_data(numeric_data < 0) = 0;
                        % Calculate column-wise mean
                        inclusion_means(unk, :) = mean(numeric_data, 1, 'omitnan');

                        app.bg_corrected_cps_inclusion =  array2table(inclusion_means, 'VariableNames', signal.Properties.VariableNames(2:end));
                    end

                end
            end
        end

        % 2.
        function calculate_sensitivity_relative_to_the_internal_standard(app)

            % first calculate the analytical sensitivity relative to the
            % internal standard element

            % Get input sensitivities and internal standards
            sensitivities = app.base.project.session.spot.sensitivities;
            internal_std = app.base.project.session.spot.internal_standard;

            % Initialize output cell array
            app.sensitivity_relative_to_internal_standard = cell(size(sensitivities,1), size(sensitivities,2));

            % Process each table in the cell array
            for prm_idx = 1:size(sensitivities,2)
                for bracket_idx = 1:size(sensitivities,1)
                    if ~isempty(sensitivities{bracket_idx,prm_idx})
                        % Get current sensitivity table and internal standards
                        curr_sens = sensitivities{bracket_idx,prm_idx};
                        curr_std = internal_std{bracket_idx,prm_idx};
                    else
                        continue
                    end
                    % Get column names
                    colNames = curr_sens.Properties.VariableNames;

                    % Convert to matrix for calculations
                    sens_matrix = table2array(curr_sens);
                    relative_sens = zeros(size(sens_matrix));

                    % Process each row
                    for unk = 1:size(sens_matrix, 1)
                        % Check if internal standard exists
                        if ~isempty(curr_std{unk})
                            % Get internal standard element name and column index
                            std_element = curr_std{unk};

                            if isequal(std_element, 'Total Oxides')

                                major_elements = { 'Na', 'Mg', 'Al', 'Si', 'P', 'K', 'Ca', 'Ti', 'Mn', 'Fe'};

                                % Get all column names from the table
                                columnNames = app.base.project.session.spot.sensitivities{bracket_idx, prm_idx}.Properties.VariableNames;

                                % Initialize array to store column indices
                                major_column_indices = zeros(1, length(major_elements));

                                % Find columns containing each element name
                                for i = 1:length(major_elements)
                                    % Find columns that start with the element name
                                    matching_cols = find(startsWith(columnNames, major_elements{i}));
                                    if ~isempty(matching_cols)
                                        % Take the first matching column if multiple exist
                                        major_column_indices(i) = matching_cols(1);
                                    else
                                        message = ['No column found for element ' major_elements{i} ' for a given PRM'];
                                        uialert(app.UIFigure,message,"Warning", ...
                                            "Icon","warning");
                                        disp(['Total Oxide Calculation: No column found for element ' major_elements{i}])
                                        return
                                    end
                                end

                                % Remove any zero indices (elements not found)
                                major_column_indices = major_column_indices(major_column_indices > 0);
                                % Extract the names of found major elements
                                major_elements_names = columnNames(major_column_indices);

                                % Reset arrays for each unknown
                                current_found_elements = major_elements_names;
                                current_major_indices = major_column_indices;

                                % let the major with the highest net-cps be
                                % the internal standard, same as in old
                                % SILLS
                                majors_intensity = app.bg_corrected_cps_matrix{unk, current_major_indices};

                                [~, intstd_element_indices] = max(majors_intensity);
                                intstd_element_name = current_found_elements(intstd_element_indices);

                                internal_standard_sensitivity = app.base.project.session.spot.sensitivities{bracket_idx, prm_idx}.(intstd_element_name{1})(unk);
                                all_analytes_sensitivity = table2array(app.base.project.session.spot.sensitivities{bracket_idx, prm_idx}(unk,:));

                                relative_sens(unk,:) = all_analytes_sensitivity ./ internal_standard_sensitivity;

                            else

                                std_col = find(strcmp(colNames, std_element));
                                % Get internal standard value
                                std_value = sens_matrix(unk, std_col);
                                % Calculate relative sensitivities for this row
                                relative_sens(unk,:) = sens_matrix(unk,:) ./ std_value;

                            end
                        end
                    end

                    % Convert result back to table and store in cell array
                    app.sensitivity_relative_to_internal_standard{bracket_idx,prm_idx} = ...
                        array2table(relative_sens, 'VariableNames', colNames);
                end
            end
        end

        function calculate_sensitivity_relative_to_the_internal_standard_incl(app)

            if isequal(app.base.project.session.meta.reductiontype, 'Inclusion')

                % Get input sensitivities and internal standards
                sensitivities = app.base.project.session.spot.sensitivities;
                internal_std = app.base.project.session.spot.internal_standard_inclusion;

                % Initialize output cell array
                app.sensitivity_relative_to_internal_standard_inclusion = cell(size(sensitivities,1), size(sensitivities,2));

                % Process each table in the cell array

                for prm_idx = 1:size(sensitivities,2)
                    for bracket_idx = 1:size(sensitivities,1)
                        if ~isempty(sensitivities{bracket_idx,prm_idx})
                            % Get current sensitivity table and internal standards
                            curr_sens = sensitivities{bracket_idx,prm_idx};
                            curr_std = internal_std{bracket_idx,prm_idx};
                        else
                            continue
                        end
                        % Get column names
                        colNames = curr_sens.Properties.VariableNames;

                        % Convert to matrix for calculations
                        sens_matrix = table2array(curr_sens);
                        relative_sens = zeros(size(sens_matrix));

                        % Process each row
                        for i = 1:size(sens_matrix, 1)
                            % Check if internal standard exists
                            if ~isempty(curr_std{i})
                                % Get internal standard element name and column index
                                std_element = curr_std{i};
                                if isequal(std_element, 'Total Oxides')
                                    continue
                                end
                                std_col = find(strcmp(colNames, std_element));

                                % Get internal standard value
                                std_value = sens_matrix(i, std_col);

                                % Calculate relative sensitivities for this row
                                relative_sens(i,:) = sens_matrix(i,:) ./ std_value;
                            end
                        end

                        % Convert result back to table and store in cell array
                        app.sensitivity_relative_to_internal_standard_inclusion{bracket_idx,prm_idx} = ...
                            array2table(relative_sens, 'VariableNames', colNames);
                    end
                end
            end
        end

        % 3.
        function calculate_matrix_mass_fractions(app)

            %%%%% this function calculates the mass fractions in several
            %%%%% passes through the dataset. it makes one pass per primary
            %%%%% reference material. this is done to make a sequential
            %%%%% data reduction possible, using the values calculated with
            %%%%% one PRM as an internal standard for another PRM

            % get the indices
            unique_PRMs = unique(app.base.project.session.spot.referencematerial);
            unique_PRMs = unique_PRMs(~strcmp(unique_PRMs, 'None'));
            % Find all Unknown spots
            all_UNK = find(~strcmp(app.base.project.session.spot.type, "PRM"));

            % calculate the mass fractions for all unknowns
            
            for prm_idx = 1:size(app.base.project.session.spot.sensitivities,2)
                for bracket_idx = 1:size(app.base.project.session.spot.sensitivities,1)
                    % Initialize the matrix_composition table for current PRM
                    current_matrix_composition = array2table(zeros(length(app.base.project.session.spot.signal_starts), numel(app.base.project.session.analytes)), ...
                        'VariableNames', app.bg_corrected_cps_matrix.Properties.VariableNames);
                    current_matrix_limit_of_detection = array2table(zeros(length(app.base.project.session.spot.signal_starts), numel(app.base.project.session.analytes)), ...
                        'VariableNames', app.bg_corrected_cps_matrix.Properties.VariableNames);

                    for i = 1:numel(all_UNK)

                        unk = all_UNK(i);

                        if isempty(app.base.project.session.spot.internal_standard{bracket_idx,prm_idx}{unk})
                            continue
                        end

                        if isempty(app.base.project.session.spot.internal_standard_mass_fraction{bracket_idx,prm_idx}{unk}) && ~isequal(app.base.project.session.spot.internal_standard{bracket_idx,prm_idx}{unk}, 'Total Oxides')
                            continue
                        end

                        if isempty(app.base.project.session.spot.internal_standard_oxide_wt{bracket_idx,prm_idx}{unk}) && isequal(app.base.project.session.spot.internal_standard{bracket_idx,prm_idx}{unk}, 'Total Oxides')
                            continue
                        end

                        if isempty(app.base.project.session.spot.Fe_ratio_for_total_oxide{bracket_idx,prm_idx}{unk}) && isequal(app.base.project.session.spot.internal_standard{bracket_idx,prm_idx}{unk}, 'Total Oxides')
                            continue
                        end

                        if ischar(app.base.project.session.spot.internal_standard_mass_fraction{bracket_idx,prm_idx}{unk}) && ...
                                startsWith(app.base.project.session.spot.internal_standard_mass_fraction{bracket_idx,prm_idx}{unk}, 'from') && ~isequal(app.base.project.session.spot.internal_standard{bracket_idx,prm_idx}{unk}, 'Total Oxides')
                            continue
                        end

                        % Initialize mass_fractions_matrix with zeros
                        mass_fractions_matrix = zeros(1, width(app.bg_corrected_cps_matrix));
                        LOD_matrix = zeros(1, width(app.bg_corrected_cps_matrix));

                        % Get column names from sensitivity table
                        sensitivity_cols = app.sensitivity_relative_to_internal_standard{bracket_idx,prm_idx}.Properties.VariableNames;

                        % Calculate mass fractions only for columns present in
                        % sensitivity table since this table represents the analytes
                        % that are also in the external standard
                        for col = 1:length(sensitivity_cols)
                            if ismember(sensitivity_cols{col}, app.bg_corrected_cps_matrix.Properties.VariableNames)
                                col_idx = find(strcmp(app.bg_corrected_cps_matrix.Properties.VariableNames, sensitivity_cols{col}));

                                internal_standard = app.base.project.session.spot.internal_standard{bracket_idx,prm_idx}{unk};

                                if isequal(internal_standard, 'Total Oxides')
                                    
                                    [internal_standard_intensity, internal_standard_mass_fraction, internal_standard_name] = total_oxide_calculation(app, bracket_idx, prm_idx, unk);

                                else % this is for the normal case
                                    internal_standard_name = internal_standard;
                                    internal_standard_intensity = app.bg_corrected_cps_matrix.(internal_standard_name)(unk);
                                    internal_standard_mass_fraction = app.base.project.session.spot.internal_standard_mass_fraction{bracket_idx,prm_idx}{unk};

                                end

                                if ~isequal(app.bg_corrected_cps_matrix.(sensitivity_cols{col})(unk) ,0)
                                    % actual calculation
                                    mass_fractions_matrix(col_idx) = (app.bg_corrected_cps_matrix.(sensitivity_cols{col})(unk) / internal_standard_intensity) * ...
                                        (internal_standard_mass_fraction / ...
                                        app.sensitivity_relative_to_internal_standard{bracket_idx,prm_idx}.(sensitivity_cols{col})(unk));
                                    % limits of detection
                                    LOD_matrix(col_idx) = calculate_limits_of_detection_matrix(app, bracket_idx, prm_idx, unk, sensitivity_cols, col, internal_standard_name, internal_standard_mass_fraction);
                                end
                            end
                        end

                        % Update the specific row in current matrix_composition with calculated mass fractions
                        current_matrix_composition{unk,:} = mass_fractions_matrix;
                         % Update the specific row in current
                         % matrix_limit_of_detection with calculated
                         % detection limit
                        current_matrix_limit_of_detection{unk,:} = LOD_matrix;

                    end

                    % Store the matrix_composition table for current PRM in the cell array
                    app.base.project.session.spot.matrix_composition{bracket_idx,prm_idx} = current_matrix_composition;
                    % Store the matrix_limits_of_detection table for current PRM in the cell array
                    app.base.project.session.spot.matrix_limits_of_detection{bracket_idx,prm_idx} = current_matrix_limit_of_detection;

                    % delete the empyt matrix_composition tables to make it
                    % look pretty
                    if isempty(app.base.project.session.spot.sensitivities{bracket_idx,prm_idx}) || ...
                            isequal(app.base.project.session.spot.sensitivities{bracket_idx,prm_idx}, zeros(0,0))
                        app.base.project.session.spot.matrix_composition{bracket_idx,prm_idx} = zeros(0,0);
                    end
                    if isempty(app.base.project.session.spot.sensitivities{bracket_idx,prm_idx}) || ...
                            isequal(app.base.project.session.spot.sensitivities{bracket_idx,prm_idx}, zeros(0,0))
                        app.base.project.session.spot.matrix_limits_of_detection{bracket_idx,prm_idx} = zeros(0,0);
                    end

                end
            end

            % Dynamic passes based on number of unique PRMs
            % this is necessary for the sequential data reduction
            % the total oxides do not play a role here anymore because they
            % cannot have the 'from XY'feature
            max_passes = length(unique_PRMs);
            for pass = 1:max_passes
                changes_made = false; % Flag to track if any values were updated in this pass

                for prm_idx = 1:size(app.base.project.session.spot.sensitivities, 2)
                    for bracket_idx = 1:size(app.base.project.session.spot.sensitivities, 1)
                        current_matrix_composition = array2table(zeros(length(app.base.project.session.spot.signal_starts), numel(app.base.project.session.analytes)), ...
                            'VariableNames', app.bg_corrected_cps_matrix.Properties.VariableNames);
                        current_matrix_limit_of_detection = array2table(zeros(length(app.base.project.session.spot.signal_starts), numel(app.base.project.session.analytes)), ...
                            'VariableNames', app.bg_corrected_cps_matrix.Properties.VariableNames);

                        for i = 1:numel(all_UNK)
                            unk = all_UNK(i);

                            % Skip if no internal standard is defined
                            if isempty(app.base.project.session.spot.internal_standard{bracket_idx,prm_idx}{unk})
                                continue
                            end

                            % Get internal standard element for current spot
                            is_element = app.base.project.session.spot.internal_standard{bracket_idx,prm_idx}{unk};
   
                            % Check if internal_standard_mass_fraction contains 'from' indicating we should use another PRM's result
                            if ischar(app.base.project.session.spot.internal_standard_mass_fraction{bracket_idx,prm_idx}{unk}) && ...
                                    startsWith(app.base.project.session.spot.internal_standard_mass_fraction{bracket_idx,prm_idx}{unk}, 'from')  && ~isequal(app.base.project.session.spot.internal_standard{bracket_idx,prm_idx}{unk}, 'Total Oxides')

                                % Extract source PRM name
                                source_prm = extractAfter(app.base.project.session.spot.internal_standard_mass_fraction{bracket_idx,prm_idx}{unk}, 'from ');
                                source_prm_idx = find(strcmp(unique_PRMs, source_prm));

                                % Get calculated value from source PRM if available
                                if ~isempty(source_prm_idx) && ~isempty(app.base.project.session.spot.matrix_composition{bracket_idx,source_prm_idx})
                                    calculated_is_value = app.base.project.session.spot.matrix_composition{bracket_idx,source_prm_idx}{unk,is_element};

                                    if calculated_is_value == 0
                                        current_matrix_composition{unk,:} = table2array(app.base.project.session.spot.matrix_composition{bracket_idx,prm_idx}(unk,:));
                                        current_matrix_limit_of_detection{unk,:} = table2array(app.base.project.session.spot.matrix_limits_of_detection{bracket_idx,prm_idx}(unk,:));
                                    elseif calculated_is_value > 0
                                        % Store original value
                                        original_is_mass_fraction = app.base.project.session.spot.internal_standard_mass_fraction{bracket_idx,prm_idx}{unk};
                                        old_values = table2array(app.base.project.session.spot.matrix_composition{bracket_idx,prm_idx}(unk,:));

                                        % Use calculated value for this calculation
                                        app.base.project.session.spot.internal_standard_mass_fraction{bracket_idx,prm_idx}{unk} = calculated_is_value;

                                        % Calculate mass fractions with sensitivity table
                                        sensitivity_cols = app.sensitivity_relative_to_internal_standard{bracket_idx,prm_idx}.Properties.VariableNames;
                                        mass_fractions_matrix = zeros(1, width(app.bg_corrected_cps_matrix));
                                        LOD_matrix = zeros(1, width(app.bg_corrected_cps_matrix));

                                        for col = 1:length(sensitivity_cols)
                                            if ismember(sensitivity_cols{col}, app.bg_corrected_cps_matrix.Properties.VariableNames)
                                                col_idx = find(strcmp(app.bg_corrected_cps_matrix.Properties.VariableNames, sensitivity_cols{col}));

                                                internal_standard_name = app.base.project.session.spot.internal_standard{bracket_idx,prm_idx}{unk};
                                                internal_standard_intensity = app.bg_corrected_cps_matrix.(char(internal_standard_name))(unk);
                                                internal_standard_mass_fraction = app.base.project.session.spot.internal_standard_mass_fraction{bracket_idx,prm_idx}{unk};

                                                if ~isequal(app.bg_corrected_cps_matrix.(sensitivity_cols{col})(unk) ,0)

                                                    mass_fractions_matrix(col_idx) = (app.bg_corrected_cps_matrix.(sensitivity_cols{col})(unk) / internal_standard_intensity) * ...
                                                        (internal_standard_mass_fraction / ...
                                                        app.sensitivity_relative_to_internal_standard{bracket_idx,prm_idx}.(sensitivity_cols{col})(unk));

                                                    % limits of detection
                                                    LOD_matrix(col_idx) = calculate_limits_of_detection_matrix(app, bracket_idx, prm_idx, unk, sensitivity_cols, col, internal_standard_name, internal_standard_mass_fraction);
                                                end
                                            end
                                        end

                                        % Restore original value
                                        app.base.project.session.spot.internal_standard_mass_fraction{bracket_idx,prm_idx}{unk} = original_is_mass_fraction;

                                        % save the data
                                        current_matrix_composition{unk,:} = mass_fractions_matrix;
                                        current_matrix_limit_of_detection{unk,:} = LOD_matrix;

                                        % Check if values changed
                                        if any(abs(mass_fractions_matrix - old_values) > 1e-10)
                                            changes_made = true;
                                        end
                                    end
                                end
                            else
                                current_matrix_composition{unk,:} = table2array(app.base.project.session.spot.matrix_composition{bracket_idx,prm_idx}(unk,:));
                                current_matrix_limit_of_detection{unk,:} = table2array(app.base.project.session.spot.matrix_limits_of_detection{bracket_idx,prm_idx}(unk,:));
                            end
                        end

                        app.base.project.session.spot.matrix_composition{bracket_idx,prm_idx} = current_matrix_composition;
                        % Store the matrix_limits_of_detection table for current PRM in the cell array
                        app.base.project.session.spot.matrix_limits_of_detection{bracket_idx,prm_idx} = current_matrix_limit_of_detection;
                    end
                end

                % If no changes were made in this pass, we can stop
                if ~changes_made && pass > 1
                    break;
                end
            end
        end

        function calculate_inclusion_mass_fractions(app)

            %%%%% this function calculates the mass fractions in several
            %%%%% passes through the dataset. it makes one pass per primary
            %%%%% reference material. this is done to make a sequential
            %%%%% data reduction possible, using the values calculated with
            %%%%% one PRM as an internal standard for another PRM
            if isequal(app.base.project.session.meta.reductiontype, 'Inclusion')

                % get the indices
                unique_PRMs = unique(app.base.project.session.spot.referencematerial);
                unique_PRMs = unique_PRMs(~strcmp(unique_PRMs, 'None'));
                % Find all Unknown spots
                all_UNK = find(~strcmp(app.base.project.session.spot.type, "PRM"));

                % calculate the mass fractions for all unknowns

                for prm_idx = 1:size(app.base.project.session.spot.sensitivities,2)
                    for bracket_idx = 1:size(app.base.project.session.spot.sensitivities,1)
                        % Initialize the inclusion_composition table for current PRM
                        current_inclusion_composition = array2table(zeros(length(app.base.project.session.spot.signal_starts), numel(app.base.project.session.analytes)), ...
                            'VariableNames', app.bg_corrected_cps_inclusion.Properties.VariableNames);
                        current_inclusion_limit_of_detection = array2table(zeros(length(app.base.project.session.spot.signal_starts), numel(app.base.project.session.analytes)), ...
                            'VariableNames', app.bg_corrected_cps_inclusion.Properties.VariableNames);

                        for i = 1:numel(all_UNK)
                            unk = all_UNK(i);

                            if isempty(app.base.project.session.spot.internal_standard_mass_fraction_inclusion{bracket_idx,prm_idx}{unk})
                                continue
                            end

                            if isempty(app.base.project.session.spot.internal_standard_inclusion{bracket_idx,prm_idx}{unk})
                                continue
                            end

                            if ischar(app.base.project.session.spot.internal_standard_mass_fraction_inclusion{bracket_idx,prm_idx}{unk}) && ...
                                    startsWith(app.base.project.session.spot.internal_standard_mass_fraction_inclusion{bracket_idx,prm_idx}{unk}, 'from')
                                continue
                            end

                            % Initialize mass_fractions_inclusion with zeros
                            mass_fractions_inclusion = zeros(1, width(app.bg_corrected_cps_inclusion));
                            LOD_inclusion = zeros(1, width(app.bg_corrected_cps_inclusion));
                            % Get column names from sensitivity table
                            sensitivity_cols = app.sensitivity_relative_to_internal_standard_inclusion{bracket_idx,prm_idx}.Properties.VariableNames;

                            % Calculate mass fractions only for columns present in
                            % sensitivity table since this table represents the analytes
                            % that are also in the external standard
                            for col = 1:length(sensitivity_cols)
                                if ismember(sensitivity_cols{col}, app.bg_corrected_cps_inclusion.Properties.VariableNames)
                                    col_idx = find(strcmp(app.bg_corrected_cps_inclusion.Properties.VariableNames, sensitivity_cols{col}));

                                    internal_standard_name = app.base.project.session.spot.internal_standard_inclusion{bracket_idx,prm_idx}{unk};
                                    internal_standard_intensity = app.bg_corrected_cps_inclusion.(char(internal_standard_name))(unk);
                                    internal_standard_mass_fraction = app.base.project.session.spot.internal_standard_mass_fraction_inclusion{bracket_idx,prm_idx}{unk};

                                    if ~isequal(app.bg_corrected_cps_inclusion.(sensitivity_cols{col})(unk) ,0)
                                        mass_fractions_inclusion(col_idx) = (app.bg_corrected_cps_inclusion.(sensitivity_cols{col})(unk) / internal_standard_intensity) * ...
                                            (internal_standard_mass_fraction / ...
                                            app.sensitivity_relative_to_internal_standard_inclusion{bracket_idx,prm_idx}.(sensitivity_cols{col})(unk));

                                        LOD_inclusion(col_idx) = calculate_limits_of_detection_inclusion(app, bracket_idx, prm_idx, unk, sensitivity_cols, col, internal_standard_name, internal_standard_mass_fraction);
                                    end
                                end
                            end

                            % Update the specific row in current inclusion_composition with calculated mass fractions
                            current_inclusion_composition{unk,:} = mass_fractions_inclusion;
                            current_inclusion_limit_of_detection{unk,:} = LOD_inclusion;
                        end

                        % Store the inclusion_composition table for current PRM in the cell array
                        app.base.project.session.spot.inclusion_composition{bracket_idx,prm_idx} = current_inclusion_composition;
                        app.base.project.session.spot.inclusion_limits_of_detection{bracket_idx,prm_idx} = current_inclusion_limit_of_detection;

                        if isempty(app.base.project.session.spot.sensitivities{bracket_idx,prm_idx}) || ...
                                isequal(app.base.project.session.spot.sensitivities{bracket_idx,prm_idx}, zeros(0,0))
                            app.base.project.session.spot.inclusion_composition{bracket_idx,prm_idx} = zeros(0,0);
                        end
                        if isempty(app.base.project.session.spot.sensitivities{bracket_idx,prm_idx}) || ...
                                isequal(app.base.project.session.spot.sensitivities{bracket_idx,prm_idx}, zeros(0,0))
                            app.base.project.session.spot.inclusion_limits_of_detection{bracket_idx,prm_idx} = zeros(0,0);
                        end

                    end
                end

                % Dynamic passes based on number of unique PRMs
                % this is necessary for the sequential data reduction
                max_passes = length(unique_PRMs);
                for pass_incl = 1:max_passes
                    changes_made_incl = false; % Flag to track if any values were updated in this pass

                    for prm_idx = 1:size(app.base.project.session.spot.sensitivities, 2)
                        for bracket_idx = 1:size(app.base.project.session.spot.sensitivities, 1)
                            current_inclusion_composition = array2table(zeros(length(app.base.project.session.spot.signal_starts), numel(app.base.project.session.analytes)), ...
                                'VariableNames', app.bg_corrected_cps_inclusion.Properties.VariableNames);
                            current_inclusion_limit_of_detection = array2table(zeros(length(app.base.project.session.spot.signal_starts), numel(app.base.project.session.analytes)), ...
                                'VariableNames', app.bg_corrected_cps_inclusion.Properties.VariableNames);

                            for i = 1:numel(all_UNK)
                                unk = all_UNK(i);

                                % Skip if no internal standard is defined
                                if isempty(app.base.project.session.spot.internal_standard_inclusion{bracket_idx,prm_idx}{unk})
                                    continue
                                end

                                % Get internal standard element for current spot
                                is_element = app.base.project.session.spot.internal_standard_inclusion{bracket_idx,prm_idx}{unk};

                                % Check if internal_standard_mass_fraction contains 'from' indicating we should use another PRM's result
                                if ischar(app.base.project.session.spot.internal_standard_mass_fraction_inclusion{bracket_idx,prm_idx}{unk}) && ...
                                        startsWith(app.base.project.session.spot.internal_standard_mass_fraction_inclusion{bracket_idx,prm_idx}{unk}, 'from')

                                    % Extract source PRM name
                                    source_prm = extractAfter(app.base.project.session.spot.internal_standard_mass_fraction_inclusion{bracket_idx,prm_idx}{unk}, 'from ');
                                    source_prm_idx = find(strcmp(unique_PRMs, source_prm));

                                    % Get calculated value from source PRM if available
                                    if ~isempty(source_prm_idx) && ~isempty(app.base.project.session.spot.inclusion_composition{bracket_idx,source_prm_idx})
                                        calculated_is_value = app.base.project.session.spot.inclusion_composition{bracket_idx,source_prm_idx}{unk,is_element};

                                        if calculated_is_value == 0
                                            current_inclusion_composition{unk,:} = table2array(app.base.project.session.spot.inclusion_composition{bracket_idx,prm_idx}(unk,:));
                                            current_inclusion_limit_of_detection{unk,:} = table2array(app.base.project.session.spot.inclusion_limits_of_detection{bracket_idx,prm_idx}(unk,:));
                                        elseif calculated_is_value > 0
                                            % Store original value
                                            original_is_mass_fraction = app.base.project.session.spot.internal_standard_mass_fraction_inclusion{bracket_idx,prm_idx}{unk};
                                            old_values = table2array(app.base.project.session.spot.inclusion_composition{bracket_idx,prm_idx}(unk,:));

                                            % Use calculated value for this calculation
                                            app.base.project.session.spot.internal_standard_mass_fraction_inclusion{bracket_idx,prm_idx}{unk} = calculated_is_value;

                                            % Calculate mass fractions with sensitivity table
                                            sensitivity_cols = app.sensitivity_relative_to_internal_standard_inclusion{bracket_idx,prm_idx}.Properties.VariableNames;
                                            mass_fractions_inclusion = zeros(1, width(app.bg_corrected_cps_inclusion));
                                            LOD_inclusion = zeros(1, width(app.bg_corrected_cps_inclusion));

                                            for col = 1:length(sensitivity_cols)
                                                if ismember(sensitivity_cols{col}, app.bg_corrected_cps_inclusion.Properties.VariableNames)
                                                    col_idx = find(strcmp(app.bg_corrected_cps_inclusion.Properties.VariableNames, sensitivity_cols{col}));

                                                    internal_standard_name = app.base.project.session.spot.internal_standard_inclusion{bracket_idx,prm_idx}{unk};
                                                    internal_standard_intensity = app.bg_corrected_cps_inclusion.(char(internal_standard_name))(unk);
                                                    internal_standard_mass_fraction = app.base.project.session.spot.internal_standard_mass_fraction_inclusion{bracket_idx,prm_idx}{unk};

                                                    if ~isequal(app.bg_corrected_cps_inclusion.(sensitivity_cols{col})(unk) ,0)
                                                        mass_fractions_inclusion(col_idx) = (app.bg_corrected_cps_inclusion.(sensitivity_cols{col})(unk) / internal_standard_intensity) * ...
                                                            (internal_standard_mass_fraction/ ...
                                                            app.sensitivity_relative_to_internal_standard_inclusion{bracket_idx,prm_idx}.(sensitivity_cols{col})(unk));
                                                    
                                                        LOD_inclusion(col_idx) = calculate_limits_of_detection_inclusion(app, bracket_idx, prm_idx, unk, sensitivity_cols, col, internal_standard_name, internal_standard_mass_fraction);
                                                    end
                                                end
                                            end

                                            % Restore original value
                                            app.base.project.session.spot.internal_standard_mass_fraction_inclusion{bracket_idx,prm_idx}{unk} = original_is_mass_fraction;
                                            
                                            current_inclusion_composition{unk,:} = mass_fractions_inclusion;
                                            current_inclusion_limit_of_detection{unk,:} = LOD_inclusion;

                                            % Check if values changed
                                            if any(abs(mass_fractions_inclusion - old_values) > 1e-10)
                                                changes_made_incl = true;
                                            end
                                        end
                                    end
                                else
                                    current_inclusion_composition{unk,:} = table2array(app.base.project.session.spot.inclusion_composition{bracket_idx,prm_idx}(unk,:));
                                    current_inclusion_limit_of_detection{unk,:} = table2array(app.base.project.session.spot.inclusion_limits_of_detection{bracket_idx,prm_idx}(unk,:));
                                end
                            end

                            app.base.project.session.spot.inclusion_composition{bracket_idx,prm_idx} = current_inclusion_composition;
                            app.base.project.session.spot.inclusion_limits_of_detection{bracket_idx,prm_idx} = current_inclusion_limit_of_detection;
                        end
                    end

                    % If no changes were made in this pass, we can stop
                    if ~changes_made_incl && pass_incl > 1
                        break;
                    end
                end
            end
        end

        %%%%%%%%%%%%%%%% Total Oxides %%%%%%%%%%%%%%%%%%%%%

        % this function is called in calculate_matrix_mass_fractions
        % it outputs the intensity and mass fraction of the major element
        % with the highest net-count rate (like in the old SILLS). The rest
        % is calculated in the calculate_matrix_mass_fractions function
        function [intstd_elements_intensity , internal_standard_mass_fraction, intstd_elements_name] = total_oxide_calculation(app, bracket_idx, prm_idx, unk)

            % first define the oxide names to get the correct columns out
            % of the table: SiO2, TiO2, Al2O3,
            % Fe2O3, FeO, MnO, MgO, CaO, Na2O, K2O, P2O5
            % Define base element names to search for
            major_elements = { 'Na', 'Mg', 'Al', 'Si', 'P', 'K', 'Ca', 'Ti', 'Mn', 'Fe'};

            % Get all column names from the table
            columnNames = app.base.project.session.spot.sensitivities{bracket_idx, prm_idx}.Properties.VariableNames;

            % Initialize array to store column indices
            major_column_indices = zeros(1, length(major_elements));

            % Find columns containing each element name
            for i = 1:length(major_elements)
                % Find columns that start with the element name
                matching_cols = find(startsWith(columnNames, major_elements{i}));
                if ~isempty(matching_cols)
                    % Take the first matching column if multiple exist
                    major_column_indices(i) = matching_cols(1);
                else
                    return
                end
            end

            % Remove any zero indices (elements not found)
            major_column_indices = major_column_indices(major_column_indices > 0);
            % Extract the names of found major elements
            major_elements_names = columnNames(major_column_indices);

            % Define oxide conversion factors
            oxide_factors = containers.Map();
            oxide_factors('Na') = 1.3480; % Na2O
            oxide_factors('Mg') = 1.6582; % MgO
            oxide_factors('Al') = 1.8895; % Al2O3
            oxide_factors('Si') = 2.1393; % SiO2
            oxide_factors('P')  = 2.2916; % P2O5
            oxide_factors('K')  = 1.2046; % K2O
            oxide_factors('Ca') = 1.3992; % CaO
            oxide_factors('Ti') = 1.6681; % TiO2
            oxide_factors('Mn') = 1.2912; % MnO
            oxide_factors('Fe') = 1.2865; % FeO
            oxide_factors('Fe2O3') = 1.4297; % Fe2O3

            %%%%%%%%%%% actual calculation %%%%%%%%%%%%%

            % Reset arrays for each unknown
            current_found_elements = major_elements_names;
            current_major_indices = major_column_indices;

            % Step 1: Calculate preliminary mass fractions and normalize
            % them to the preliminary mass fraction of the internal std
            intensity = app.bg_corrected_cps_matrix{unk, current_major_indices};
            [intstd_elements_intensity, intstd_element_indices] = max(intensity); % define the internal standard as the major with the highest net-cps
            intstd_elements_name = current_found_elements(intstd_element_indices);

            sensitivity = table2array(app.base.project.session.spot.sensitivities{bracket_idx, prm_idx}(unk, current_major_indices));

            preliminary_mass_fraction = intensity ./ sensitivity;

            normalized_mass_fraction = preliminary_mass_fraction ./ preliminary_mass_fraction(intstd_element_indices);

            % % Handle Fe2+/Fe3+ ratio
            % fe_ratio = app.base.project.session.spot.Fe_ratio_for_total_oxide{bracket_idx, prm_idx}{unk};
            % if ~isempty(fe_ratio) && ~isequal(fe_ratio, 1)
            %     if any(fe_idx)
            %         fe_total = normalized_mass_fraction(fe_idx);
            %         fe2_fraction = fe_total * fe_ratio;
            %         fe3_fraction = fe_total * (1 - fe_ratio);
            %
            %         % Update Fe calculations
            %         normalized_mass_fraction(fe_idx) = fe2_fraction;
            %         % Add Fe2O3 contribution
            %         normalized_mass_fraction = [normalized_mass_fraction fe3_fraction];
            %         current_found_elements = [current_found_elements {'Fe2O3'}];
            %     end
            % end

            %%%%%%%%%%% For Julia!!! %%%%%%%%%%%%
            % Step 2: Handle Fe2+/Fe3+ ratio like old SILLS
            % get the index of Fe for later Fe2+/Fe3+ correction
            fe_idx = find(contains(major_elements_names, 'Fe'));
            fe_ratio = app.base.project.session.spot.Fe_ratio_for_total_oxide{bracket_idx, prm_idx}{unk};
            if ~isempty(fe_ratio) && ~isequal(fe_ratio, 1)
                if any(fe_idx)
                    fe_total = normalized_mass_fraction(fe_idx);
                    % Calculate Fe2O3 and FeO using the constants from old script
                    % 0.699480/0.777314 is the ratio of Fe in Fe2O3 vs FeO
                    fe_constant = 0.699480/0.777314;

                    % Calculate fractions using the same formula as old script
                    fe2o3_fraction = fe_total * (1-(1/(1+(fe_constant)*((1-fe_ratio)/fe_ratio))));
                    feo_fraction = fe_total * (1/(1+(fe_constant)*((1-fe_ratio)/fe_ratio)));

                    % Update Fe calculations
                    normalized_mass_fraction(fe_idx) = feo_fraction;
                    % Add Fe2O3 contribution
                    normalized_mass_fraction = [normalized_mass_fraction fe2o3_fraction];
                    current_found_elements = [current_found_elements {'Fe2O3'}];
                end
            end

            % Step 3: Convert to oxides using current elements
            oxide_wt_percent = zeros(size(normalized_mass_fraction));
            for j = 1:length(current_found_elements)
                element = current_found_elements{j};
                base_element = regexp(element, '^[A-Za-z]+', 'match');
                if ~isempty(base_element) && isKey(oxide_factors, base_element{1})
                    if strcmp(element, 'Fe2O3')
                        oxide_wt_percent(j) = normalized_mass_fraction(j) * oxide_factors('Fe2O3');
                    else
                        oxide_wt_percent(j) = normalized_mass_fraction(j) * oxide_factors(base_element{1});
                    end
                end
            end

            % Step 4: Normalize to input total oxide wt%
            if ~isempty(app.base.project.session.spot.Fe_ratio_for_total_oxide{bracket_idx, prm_idx}{unk})
                std_value = app.base.project.session.spot.internal_standard_oxide_wt{bracket_idx, prm_idx}{unk};
                if ~isempty(std_value)
                    oxide_wt_percent = oxide_wt_percent * (std_value / sum(oxide_wt_percent));
                end
            end

            % Step 5: Convert major oxide weight percentages back to µg/g
            mass_fractions_ugg = zeros(size(oxide_wt_percent));
            for j = 1:length(current_found_elements)
                element = current_found_elements{j};
                base_element = regexp(element, '^[A-Za-z]+', 'match');
                if ~isempty(base_element)
                    if contains(element, 'Fe') && ~strcmp(element, 'Fe2O3')
                        % Store Fe2+ value
                        mass_fractions_ugg(j) = (oxide_wt_percent(j) / oxide_factors('Fe')) * 10000;
                        current_found_elements{j} = 'Fe2';

                    elseif strcmp(element, 'Fe2O3')
                        % Store Fe3+ value
                        mass_fractions_ugg(j) = (oxide_wt_percent(j) / oxide_factors('Fe2O3')) * 10000;
                        current_found_elements{j} = 'Fe3';

                    elseif isKey(oxide_factors, base_element{1})
                        mass_fractions_ugg(j) = (oxide_wt_percent(j) / oxide_factors(base_element{1})) * 10000;
                    end
                end
            end

            % Initialize mass_fractions table with Fe2+/Fe3+ columns
            major_mass_fractions = array2table(zeros(size(app.bg_corrected_cps_matrix,1), length([major_elements_names, {'Fe2', 'Fe3'}])), ...
                'VariableNames', [major_elements_names, {'Fe2', 'Fe3'}]);

            major_mass_fractions{unk, current_found_elements} = mass_fractions_ugg;

            % Find the column that contains 'Fe' in the header
            fe_col = find(contains(major_mass_fractions.Properties.VariableNames, 'Fe') & ...
                ~strcmp(major_mass_fractions.Properties.VariableNames, 'Fe2') & ...
                ~strcmp(major_mass_fractions.Properties.VariableNames, 'Fe3'));

            % Sum Fe2 and Fe3 to ge the total Fe
            major_mass_fractions{:, fe_col} = major_mass_fractions.Fe2 + major_mass_fractions.Fe3;

            % do this properly if the Fe2 and Fe3 µg/g should be stored
            % somewhere
            % if ~isfield(app, 'Fe2_3')
            %     app.Fe2_3 = array2table(zeros(size(app.bg_corrected_cps_matrix,1), 2), ...
            %         'VariableNames', {'Fe2', 'Fe3'});
            %     % Store Fe2 and Fe3 in app properties without overwriting previous values
            %     app.Fe2_3.Fe2(unk) = major_mass_fractions.Fe2(unk);
            %     app.Fe2_3.Fe3(unk) = major_mass_fractions.Fe3(unk);
            % end
            
            % Remove Fe2 and Fe3 columns from major_mass_fractions
            major_mass_fractions.Fe2 = [];
            major_mass_fractions.Fe3 = [];

            internal_standard_mass_fraction = major_mass_fractions.(intstd_elements_name{1})(unk);

        end


        %%%%%%%%%%%%%%%% Limits of Detection %%%%%%%%%%%%%%%%%%%%%

        function LOD_matrix = calculate_limits_of_detection_matrix(app, bracket_idx, prm_idx, unk, sensitivity_cols, col, internal_standard_name, internal_standard_mass_fraction)
            % According to Pettke 2012

            background_start = app.base.project.session.spot.background_starts;
            background_end = app.base.project.session.spot.background_ends;

            % calculate the mean for the matrix
            matrix_1_start = app.base.project.session.spot.matrix_1_starts;
            matrix_1_end = app.base.project.session.spot.matrix_1_ends;

            matrix_2_start = app.base.project.session.spot.matrix_2_starts;
            matrix_2_end = app.base.project.session.spot.matrix_2_ends;

            matrix_3_start = app.base.project.session.spot.matrix_3_starts;
            matrix_3_end = app.base.project.session.spot.matrix_3_ends;

            % case of full measurement input
            if isequal(app.base.project.session.meta.datatype, 'Continuous')
                signal = app.base.project.session.ms_data;
            end

            % case of single_spot input
            if isequal(app.base.project.session.meta.datatype, 'Single')
                signal = app.base.project.session.spot.ms_data_single_spot{unk};
            end

            % mean_background_cps = table2array(mean(signal(background_start{unk}:background_end{unk}, 2:end), 1));
            mean_background_cps = mean(signal.(sensitivity_cols{col})(background_start{unk}:background_end{unk}));

            number_of_sweeps_bg = numel(background_start{unk}:background_end{unk});

            number_of_sweeps_matrix = numel(matrix_1_start{unk}:matrix_1_end{unk}) + numel(matrix_2_start{unk}:matrix_2_end{unk}) + numel(matrix_3_start{unk}:matrix_3_end{unk});

            % i dont know why this is done like that but the old SILLS also
            % does it and it seems to give the same values. Also in Pettke
            % 2012 it states "sensitivity of element in cps/µg/g as derived from the external standard multiplied by the relative sensitivity factor"
            %sensitivity = app.sensitivity_relative_to_internal_standard{bracket_idx,prm_idx}.(sensitivity_cols{col})(unk) .* (app.bg_corrected_cps_matrix.(app.base.project.session.spot.internal_standard{bracket_idx,prm_idx}{unk})(unk)/app.base.project.session.spot.internal_standard_mass_fraction{bracket_idx,prm_idx}{unk});
            sensitivity = app.sensitivity_relative_to_internal_standard{bracket_idx,prm_idx}.(sensitivity_cols{col})(unk) .* (app.bg_corrected_cps_matrix.(char(internal_standard_name))(unk)/internal_standard_mass_fraction);

            % dwelltime in seconds
            dwelltime = app.base.project.session.dwelltimes.(sensitivity_cols{col});
            
            LOD_matrix = (3.29 * (mean_background_cps * dwelltime * number_of_sweeps_matrix * (1 + number_of_sweeps_matrix / number_of_sweeps_bg)).^0.5 + 2.71)...
                / (number_of_sweeps_matrix * dwelltime * sensitivity);

        end

        function LOD_inclusion = calculate_limits_of_detection_inclusion(app, bracket_idx, prm_idx, unk, sensitivity_cols, col, internal_standard_name, internal_standard_mass_fraction)
            % According to Pettke 2012

            % %%%%%%%%%%% Background as "Background" for Inclusion %%%%%%%%%
            % background_start = app.base.project.session.spot.background_starts;
            % background_end = app.base.project.session.spot.background_ends;
            % 
            % inclusion_start = app.base.project.session.spot.inclusion_1_starts;
            % inclusion_end = app.base.project.session.spot.inclusion_1_ends;
            % 
            % % case of full measurement input
            % if isequal(app.base.project.session.meta.datatype, 'Continuous')
            %     signal = app.base.project.session.ms_data;
            % end
            % 
            % % case of single_spot input
            % if isequal(app.base.project.session.meta.datatype, 'Single')
            %     signal = app.base.project.session.spot.ms_data_single_spot{unk};
            % end
            % 
            % % mean_background_cps = table2array(mean(signal(background_start{unk}:background_end{unk}, 2:end), 1));
            % mean_background_cps = mean(signal.(sensitivity_cols{col})(background_start{unk}:background_end{unk}));
            % 
            % number_of_sweeps_bg = numel(background_start{unk}:background_end{unk});
            % 
            % number_of_sweeps_inclusion = numel(inclusion_start{unk}:inclusion_end{unk});
            % 
            % % i dont know why this is done like that but the old SILLS also
            % % does it and it seems to give the same values. Also in Pettke
            % % 2012 it states "sensitivity of element in cps/µg/g as derived from the external standard multiplied by the relative sensitivity factor"
            % sensitivity = app.sensitivity_relative_to_internal_standard{bracket_idx,prm_idx}.(sensitivity_cols{col})(unk) .* (app.bg_corrected_cps_inclusion.(app.base.project.session.spot.internal_standard{bracket_idx,prm_idx}{unk})(unk)/app.base.project.session.spot.internal_standard_mass_fraction{bracket_idx,prm_idx}{unk});
            % 
            % % dwelltime in seconds
            % dwelltime = app.base.project.session.dwelltimes.(sensitivity_cols{col});
            % 
            % LOD_inclusion = (3.29 * (mean_background_cps * dwelltime * number_of_sweeps_inclusion * (1 + number_of_sweeps_inclusion / number_of_sweeps_bg)).^0.5 + 2.71)...
            %     / (number_of_sweeps_inclusion * dwelltime * sensitivity);


            %%%%%%%%% Background-corrected Matrix as "Background" for Inclusion %%%%%%%%%

            inclusion_start = app.base.project.session.spot.inclusion_1_starts;
            inclusion_end = app.base.project.session.spot.inclusion_1_ends;

            matrix_1_start = app.base.project.session.spot.matrix_1_starts;
            matrix_1_end = app.base.project.session.spot.matrix_1_ends;

            matrix_2_start = app.base.project.session.spot.matrix_2_starts;
            matrix_2_end = app.base.project.session.spot.matrix_2_ends;

            matrix_3_start = app.base.project.session.spot.matrix_3_starts;
            matrix_3_end = app.base.project.session.spot.matrix_3_ends;

            mean_matrix_cps = app.bg_corrected_cps_matrix.(sensitivity_cols{col})(unk);

            number_of_sweeps_inclusion = numel(inclusion_start{unk}:inclusion_end{unk});

            number_of_sweeps_matrix = numel(matrix_1_start{unk}:matrix_1_end{unk}) + numel(matrix_2_start{unk}:matrix_2_end{unk}) + numel(matrix_3_start{unk}:matrix_3_end{unk});

            % i dont know why this is done like that but the old SILLS also
            % does it and it seems to give the same values. Also in Pettke
            % 2012 it states "sensitivity of element in cps/µg/g as derived from the external standard multiplied by the relative sensitivity factor"
            sensitivity = app.sensitivity_relative_to_internal_standard_inclusion{bracket_idx,prm_idx}.(sensitivity_cols{col})(unk) .* (app.bg_corrected_cps_inclusion.(char(internal_standard_name))(unk)/internal_standard_mass_fraction);

            % dwelltime in seconds
            dwelltime = app.base.project.session.dwelltimes.(sensitivity_cols{col});

            LOD_inclusion = (3.29 * (mean_matrix_cps * dwelltime * number_of_sweeps_inclusion * (1 + number_of_sweeps_inclusion / number_of_sweeps_matrix)).^0.5 + 2.71)...
                / (number_of_sweeps_inclusion * dwelltime * sensitivity);

        end


    end


    methods (Access = public)

        %%%%%%%%%%%%%%%%%%%%%%%%%% API %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        function [settings] = assign_internal_standard(app, settings)
            try
                test_state = true; % is needed later as a failsafe so that no broken data gets into the data structure

                % if settings is not sent or something wrong is put in
                if not(exist('settings', 'var')) || not(isstruct(settings))
                    settings = struct();
                end

                % if the internal_standard is not input
                if not(isfield(settings,'internal_standard')) || isempty(settings.internal_standard)
                    % now return the settings with the options for type:
                    settings.internal_standard.Options={"All Analyte Names or Total Oxides"};
                    disp('The internal standard must be provided in settings.internal_standard');
                    test_state = false;
                end

                % if the internal_standard_mass_fraction is not input
                if not(isfield(settings,'internal_standard_mass_fraction')) || isempty(settings.internal_standard_mass_fraction)
                    settings.internal_standard_mass_fraction.Options={"Mass fraction in µg/g or wt% total oxides"};
                    test_state = false;
                    disp('Internal standard mass fractions must be provided in settings.internal_standard_mass_fraction');
                end

                % if the internal_standard is not input
                if not(isfield(settings,'spotnumber_for_internal_standard')) || isempty(settings.spotnumber_for_internal_standard)
                    settings.spotnumber_for_internal_standard.Options={"Array of spotnumbers"};
                    disp('Spotnumbers for the internal standard must be provided in settings.spotnumber_for_internal_standard');
                    return
                end

                if not(isfield(settings,'internal_standard_for_primary_reference_material')) || isempty(settings.internal_standard_for_primary_reference_material)
                    settings.internal_standard_for_primary_reference_material.Options=string(unique(app.base.project.session.spot.referencematerial));
                    test_state = false;
                    disp('Primary reference material name for internal standard mass fractions must be provided in settings.internal_standard_for_primary_reference_material');
                end


                if not(isfield(settings,'internal_standard_for_bracket_id')) || isempty(settings.internal_standard_for_bracket_id)
                    settings.internal_standard_for_bracket_id.Options=string(unique(settings.bracket_id));
                    test_state = false;
                    disp('Bracket_id for internal standard mass fractions must be provided in settings.internal_standard_for_bracket_id');
                end

                % if not(isfield(settings,'internal_standard_oxide_wt')) || isempty(settings.internal_standard_oxide_wt)
                %     settings.internal_standard_oxide_wt.Options = 1:100;
                %     test_state = false;
                %     disp('wt% total oxide must be provided in settings.internal_standard_oxide_wt');
                % end
                % 
                % if not(isfield(settings,'Fe_ratio_for_total_oxide')) || isempty(settings.Fe_ratio_for_total_oxide)
                %     settings.Fe_ratio_for_total_oxide.Options = 0:0.01:1;
                %     test_state = false;
                %     disp('FeO/(FeO + Fe2O3) ratio must be provided in settings.Fe_ratio_for_total_oxide');
                % end

                internal_standard_for_primary_reference_material = settings.internal_standard_for_primary_reference_material;
                spotnumber_for_internal_standard = settings.spotnumber_for_internal_standard;
                internal_standard = settings.internal_standard;
                internal_standard_mass_fraction = settings.internal_standard_mass_fraction;
                bracket_ids = settings.internal_standard_for_bracket_id;

                % I will implement this total oxide shit if it is really
                % needed
                %oxide_wt = settings.internal_standard_oxide_wt;
                %fe_ratio = settings.Fe_ratio_for_total_oxide;

                % if test_state = false, push the data from the cache to the
                % data structure
                if test_state == false
                    return
                else

                    % Get unique PRMs
                    unique_PRMs = unique(app.base.project.session.spot.referencematerial);
                    unique_PRMs = unique_PRMs(~strcmp(unique_PRMs, 'None'));
                    unique_brackets = unique(bracket_ids);

                    % Process each entry
                    for i = 1:length(internal_standard_for_primary_reference_material)
                        % Find corresponding PRM index
                        prm_idx = find(strcmp(unique_PRMs, internal_standard_for_primary_reference_material(i)), 1);
                        if isempty(prm_idx)
                            continue;
                        end

                        % Get bracket ID for this entry
                        current_bracket = bracket_ids(i);

                        % Find spot index within the bracket
                        spot_idx = spotnumber_for_internal_standard(i);

                        % Store data in appropriate location
                        app.base.project.session.spot.internal_standard{current_bracket, prm_idx}{spot_idx} = internal_standard(i);
                        app.base.project.session.spot.internal_standard_mass_fraction{current_bracket, prm_idx}{spot_idx} = internal_standard_mass_fraction(i);


                    end
                end

                if isfield(settings, 'quantification_module_GUI_update_state') && settings.quantification_module_GUI_update_state == true
                    update_quantification_module_GUI(app)
                end

            catch ME
                DisplayErrorNG(app,ME,'assign_internal_standard')

                message = "assign_internal_standard failed";
                uialert(app.UIFigure,message,"Warning", ...
                    "Icon","warning");
                disp("assign_internal_standard failed")
            end

        end

        %%%%%%%%%%%%%% Quantification Wrapper %%%%%%%%%%%%%
        function quantification_wrapper(app)

            % Matrix/Host
            calculate_background_corrected_matrix_intensities(app)
            calculate_sensitivity_relative_to_the_internal_standard(app)
            calculate_matrix_mass_fractions(app)

            % Inclusion
            calculate_background_corrected_inclusion_intensities(app)
            calculate_sensitivity_relative_to_the_internal_standard_incl(app)
            calculate_inclusion_mass_fractions(app)

        end
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%


    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, sourceapp, project_name, project_id, session_id, data)


            if exist("sourceapp","var")
                app.sourceapp = sourceapp;
            end% input argument of startupfcn

            if exist("project_name", "var")
                app.base.project_name = project_name;
            end

            %% when the show session button is pressed & when the Interval_Module is called from another Module
            if  exist("data", "var") % e.g. for API call

                app.base.project.session =  app.sourceapp.base(project_id).project(session_id).session;
                app.base.project.session.meta.module_status.quantification = true;
                app.editstatus = true; % always set to true so that the quantification triggers at the end


            elseif exist("project_id","var") && exist("session_id","var") % coming from the base, GUI stuff

                app.base.project.session =  app.sourceapp.base(project_id).project(session_id).session;

            end

            app.project_id = project_id;
            app.session_id = session_id;
            app.UIFigure.Name = [char(app.base.project_name) ' - ' char(app.base.project.session.session_name)]; %string(app.base.project.session.session_name);

            if ~isfield(app.base.project.session.spot, 'internal_standard') || ...
                    isempty(app.base.project.session.spot.internal_standard)
                app.base.project.session.spot.internal_standard = repmat({cell(size(app.base.project.session.spot.signal_starts,1),1)}, ...
                    size(app.base.project.session.spot.sensitivities,1), size(app.base.project.session.spot.sensitivities,2));
            end

            if ~isfield(app.base.project.session.spot, 'internal_standard_mass_fraction') || ...
                    isempty(app.base.project.session.spot.internal_standard_mass_fraction)
                app.base.project.session.spot.internal_standard_mass_fraction = repmat({cell(size(app.base.project.session.spot.signal_starts,1),1)}, ...
                    size(app.base.project.session.spot.sensitivities,1), size(app.base.project.session.spot.sensitivities,2));
            end

            if ~isfield(app.base.project.session.spot, 'internal_standard_oxide_wt') || ...
                    isempty(app.base.project.session.spot.internal_standard_oxide_wt)
                app.base.project.session.spot.internal_standard_oxide_wt = repmat({cell(size(app.base.project.session.spot.signal_starts,1),1)}, ...
                    size(app.base.project.session.spot.sensitivities,1), size(app.base.project.session.spot.sensitivities,2));
            end

            if ~isfield(app.base.project.session.spot, 'Fe_ratio_for_total_oxide') || ...
                    isempty(app.base.project.session.spot.Fe_ratio_for_total_oxide)
                app.base.project.session.spot.Fe_ratio_for_total_oxide = repmat({cell(size(app.base.project.session.spot.signal_starts,1),1)}, ...
                    size(app.base.project.session.spot.sensitivities,1), size(app.base.project.session.spot.sensitivities,2));
            end
            
            if ~isfield(app.base.project.session.spot, 'matrix_composition')
                % Initialize cell array for storing matrix_composition tables for each PRM
                app.base.project.session.spot.matrix_composition = cell(size(app.base.project.session.spot.sensitivities,1), size(app.base.project.session.spot.sensitivities,2));
            end

            if ~isfield(app.base.project.session.spot, 'matrix_limits_of_detection')
                % Initialize cell array for storing matrix_composition tables for each PRM
                app.base.project.session.spot.matrix_limits_of_detection = cell(size(app.base.project.session.spot.sensitivities,1), size(app.base.project.session.spot.sensitivities,2));
            end

            if isequal(app.base.project.session.meta.reductiontype, 'Inclusion')

                if ~isfield(app.base.project.session.spot, 'internal_standard_inclusion') || ...
                        isempty(app.base.project.session.spot.internal_standard_inclusion)
                    app.base.project.session.spot.internal_standard_inclusion = repmat({cell(size(app.base.project.session.spot.signal_starts,1),1)}, ...
                        size(app.base.project.session.spot.sensitivities,1), size(app.base.project.session.spot.sensitivities,2));
                end

                if ~isfield(app.base.project.session.spot, 'internal_standard_mass_fraction_inclusion') || ...
                        isempty(app.base.project.session.spot.internal_standard_mass_fraction_inclusion)
                    app.base.project.session.spot.internal_standard_mass_fraction_inclusion = repmat({cell(size(app.base.project.session.spot.signal_starts,1),1)}, ...
                        size(app.base.project.session.spot.sensitivities,1), size(app.base.project.session.spot.sensitivities,2));
                end

                if ~isfield(app.base.project.session.spot, 'inclusion_composition')
                    % Initialize cell array for storing matrix_composition tables for each PRM
                    app.base.project.session.spot.inclusion_composition = cell(size(app.base.project.session.spot.sensitivities,1), size(app.base.project.session.spot.sensitivities,2));
                end

                if ~isfield(app.base.project.session.spot, 'inclusion_limits_of_detection')
                    % Initialize cell array for storing matrix_composition tables for each PRM
                    app.base.project.session.spot.inclusion_limits_of_detection = cell(size(app.base.project.session.spot.sensitivities,1), size(app.base.project.session.spot.sensitivities,2));
                end

            end

            update_progress_buttons(app)
            update_quantification_module_GUI(app)

        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            changes = check_changes(app);
            if changes == false
                return
            end
            app.editstatus = true;
            
            app.base.project.session.spot.internal_standard = repmat({cell(size(app.base.project.session.spot.signal_starts,1),1)}, 1, size(app.base.project.session.spot.sensitivities,2));
            app.base.project.session.spot.internal_standard_mass_fraction = repmat({cell(size(app.base.project.session.spot.signal_starts,1),1)}, 1, size(app.base.project.session.spot.sensitivities,2));
            app.base.project.session.spot.internal_standard_inclusion = repmat({cell(size(app.base.project.session.spot.signal_starts,1),1)}, 1, size(app.base.project.session.spot.sensitivities,2));
            app.base.project.session.spot.internal_standard_mass_fraction_inclusion = repmat({cell(size(app.base.project.session.spot.signal_starts,1),1)}, 1, size(app.base.project.session.spot.sensitivities,2));
            app.base.project.session.spot.Fe_ratio_for_total_oxide = repmat({cell(size(app.base.project.session.spot.signal_starts,1),1)}, ...
                    size(app.base.project.session.spot.sensitivities,1), size(app.base.project.session.spot.sensitivities,2));
            app.base.project.session.spot.internal_standard_oxide_wt = repmat({cell(size(app.base.project.session.spot.signal_starts,1),1)}, ...
                    size(app.base.project.session.spot.sensitivities,1), size(app.base.project.session.spot.sensitivities,2));
            app.base.project.session.spot.matrix_composition = cell(size(app.base.project.session.spot.sensitivities,1), size(app.base.project.session.spot.sensitivities,2));


            update_quantification_module_GUI(app)

        end

        % Selection changed function: MatrixTable
        function MatrixTableSelectionChanged(app, event)
            app.selected_spot = unique(app.MatrixTable.Selection(:,1));
            app.selected_table = 'MatrixTable';
            update_quantification_module_GUI(app)
        end

        % Cell edit callback: MatrixTable
        function MatrixTableCellEdit(app, event)
            changes = check_changes(app);
            if changes == false
                return
            end
            app.editstatus = true;

            indices = event.Indices;
            newData = event.NewData;

            % Validate newData
            if ischar(newData) || isstring(newData)
                if ~startsWith(lower(newData), 'from')
                    % Try to convert to number
                    try
                        newData = str2double(newData);
                        if isnan(newData)
                            newData = [];
                        end
                    catch
                        newData = [];
                    end
                end
            elseif ~isnumeric(newData)
                newData = [];
            elseif isnan(newData)
                newData = [];
            elseif newData < 0 
                newData = [];
                % Check if it's the Fe ratio column and validate range for numeric inputs
            elseif strcmp(app.MatrixTable.ColumnName{indices(2)}, 'FeO/(FeO + Fe2O3)') && (newData < 0 || newData > 1)
                newData = [];
            elseif strcmp(app.MatrixTable.ColumnName{indices(2)}, '% Total Oxides') && (newData < 0 || newData > 100)
                newData = [];
            end

            if strcmp(event.PreviousData,'----------------')
                newData = event.PreviousData;
                app.MatrixTable.Data.(indices(2))(indices(1)) = {newData};
            else
                for spot_idx = app.selected_spot'
                    switch app.MatrixTable.ColumnName{indices(2)}
                        case '[µg/g]'
                            app.base.project.session.spot.internal_standard_mass_fraction{app.BracketIdDropDown.Value, app.selected_PRM}{spot_idx} = newData;
                        case '% Total Oxides'
                            app.base.project.session.spot.internal_standard_oxide_wt{app.BracketIdDropDown.Value, app.selected_PRM}{spot_idx} = newData;
                        case 'FeO/(FeO + Fe2O3)'
                            app.base.project.session.spot.Fe_ratio_for_total_oxide{app.BracketIdDropDown.Value, app.selected_PRM}{spot_idx} = newData;
                    end
                end
            end

            update_quantification_module_GUI(app)
        end

        % Value changed function: AnalyteListBox
        function AnalyteListBoxValueChanged(app, event)
            changes = check_changes(app);
            if changes == false
                return
            end
            app.editstatus = true;


            % Check if any selected spot has dashes
            hasDashes = false;
            for spot_idx = app.selected_spot'
                currentValue = app.MatrixTable.Data.(3){spot_idx};
                if strcmp(currentValue, '----------------')
                    hasDashes = true;
                    break;
                end
            end

            % Only update if no dashes found
            if ~hasDashes
                newValues = repmat({app.AnalyteListBox.Value}, length(app.selected_spot), 1);
                if strcmp(app.selected_table, 'InclusionTable')
                    app.base.project.session.spot.internal_standard_inclusion{app.BracketIdDropDown.Value, app.selected_PRM}(app.selected_spot) = newValues;
                elseif strcmp(app.selected_table, 'MatrixTable')
                    app.base.project.session.spot.internal_standard{app.BracketIdDropDown.Value, app.selected_PRM}(app.selected_spot) = newValues;
                end
            end

            app.AnalyteListBox.Value = '==================';

            update_quantification_module_GUI(app)

        end

        % Value changed function: fromPRMListBox
        function fromPRMListBoxValueChanged(app, event)
            changes = check_changes(app);
            if changes == false
                return
            end
            app.editstatus = true;


            % Check if any selected spot has dashes
            hasDashes = false;
            for spot_idx = app.selected_spot'
                if strcmp(app.selected_table, 'MatrixTable')
                    currentValue = app.MatrixTable.Data.(4){spot_idx};
                    if strcmp(currentValue, '----------------')
                        hasDashes = true;
                        break;
                    end
                elseif strcmp(app.selected_table, 'InclusionTable')
                    currentValue = app.InclusionTable.Data.(4){spot_idx};
                    if strcmp(currentValue, '----------------')
                        hasDashes = true;
                        break;
                    end
                end
            end

            % Only update if no dashes found
            if ~hasDashes
                newValues = repmat({app.fromPRMListBox.Value}, length(app.selected_spot), 1);
                if strcmp(app.selected_table, 'InclusionTable')
                    app.base.project.session.spot.internal_standard_mass_fraction_inclusion{app.BracketIdDropDown.Value, app.selected_PRM}(app.selected_spot) = newValues;
                elseif strcmp(app.selected_table, 'MatrixTable')
                    app.base.project.session.spot.internal_standard_mass_fraction{app.BracketIdDropDown.Value, app.selected_PRM}(app.selected_spot) = newValues;
                end
            end

            app.fromPRMListBox.Value = '==================';

            update_quantification_module_GUI(app)
        end

        % Value changed function: SpotnamecontainsEditField
        function SpotnamecontainsEditFieldValueChanged(app, event)
 

        end

        % Button pushed function: ApplyButton
        function ApplyButtonPushed(app, event)
            changes = check_changes(app);
            if changes == false
                return
            end
            app.editstatus = true;


           selected_string = app.SpotnamecontainsEditField.Value;

            % Initialize an empty array to store the indices of the selected spots
            selected_spot_indices = [];

            % Iterate through the spot names
            for i = 1:length(app.base.project.session.spotname)
                % Check if the spot name contains the selected string
                if contains(app.base.project.session.spotname{i}, selected_string) && ismember(i, unique(app.all_white_cells(:,1)))
                    % If it does, add the index to the selected_spot_indices array
                    selected_spot_indices(end+1) = i; %#ok
                end
            end

            % Store the selected spot indices in the app property
            %app.selected_spot = selected_spot_indices;

            % Validate newData
            if ischar(app.StringCompareInputEditField.Value) || isstring(app.StringCompareInputEditField.Value)
                if ~startsWith(lower(app.StringCompareInputEditField.Value), 'from')
                    % Try to convert to number
                    try
                        app.StringCompareInputEditField.Value = str2double(app.StringCompareInputEditField.Value);
                        if isnan(app.StringCompareInputEditField.Value)
                            app.StringCompareInputEditField.Valueata = [];
                        end
                    catch
                        app.StringCompareInputEditField.Value = [];
                    end
                end
            elseif ~isnumeric(app.StringCompareInputEditField.Value)
                app.StringCompareInputEditField.Value = [];
            elseif isnan(app.StringCompareInputEditField.Value)
                app.StringCompareInputEditField.Value = [];
            elseif app.StringCompareInputEditField.Value < 0 
                app.StringCompareInputEditField.Value = [];
                % Check if it's the Fe ratio column and validate range for numeric inputs
            elseif strcmp(app.StringCompareDropDown.Value, 'FeO/(FeO + Fe2O3)') && (app.StringCompareInputEditField.Value < 0 || app.StringCompareInputEditField.Value > 1)
                app.StringCompareInputEditField.Value = [];
            elseif strcmp(app.StringCompareDropDown.Value, '% Total Oxides') && (app.StringCompareInputEditField.Value < 0 || app.StringCompareInputEditField.Value > 100)
                app.StringCompareInputEditField.Value = [];
            end
            
            % Check if we have a valid mass fraction input
            if ~isempty(app.StringCompareInputEditField.Value) && ~isnan(app.StringCompareInputEditField.Value)

                newValues = repmat({app.StringCompareInputEditField.Value}, length(selected_spot_indices), 1);
                switch app.StringCompareDropDown.Value
                    case 'µg/g'
                        app.base.project.session.spot.internal_standard_mass_fraction{app.BracketIdDropDown.Value, app.selected_PRM}(selected_spot_indices) = newValues;
                    case '% Total Oxides'
                        app.base.project.session.spot.internal_standard_oxide_wt{app.BracketIdDropDown.Value, app.selected_PRM}(selected_spot_indices) = newValues;
                    case 'FeO/(FeO + Fe2O3)'
                        app.base.project.session.spot.Fe_ratio_for_total_oxide{app.BracketIdDropDown.Value, app.selected_PRM}(selected_spot_indices) = newValues;

                end

            end

            % Update the GUI
            update_quantification_module_GUI(app);
        end

        % Button pushed function: ProceedtoResultsModuleButton
        function ProceedtoResultsModuleButtonPushed(app, event)


            if app.editstatus == true

                try
                    quantification_wrapper(app)
                catch ME
                    message = "Quantification failed";
                    uialert(app.UIFigure,message,"Warning", ...
                        "Icon","warning");
                    disp("Quantification failed")
                    return
                end
            end

            app.base.project.session.meta.module_status.quantification = true;

            if app.editstatus == true
                % uiconfirm ask user if finished
                fig = app.UIFigure;
                msg = "Do you want to proceed and save the data?";
                title = "ICP-Base";
                selection = uiconfirm(fig,msg,title, ...
                    "Options",["Save","Don't save","Cancel"], ...
                    "DefaultOption",1,"CancelOption",3);
            else
                selection = 'Save';
            end

            switch selection
                case 'Save'
                    [app.project_id, app.session_id] = save_to_base(app.sourceapp, app.project_id, app.base.project.session, app.session_id, app.base.project_name, settings);
                    Results_Module(app.sourceapp, app.base.project_name, app.project_id, app.session_id)
                    delete(app)
                case "Don't save"
                    Results_Module(app.sourceapp, app.base.project_name, app.project_id, app.session_id)
                    delete(app)
                case 'Cancel'
                    return
            end


        end

        % Menu selected function: SaveSessiontoBaseandExitMenu
        function SaveSessiontoBaseandExitMenuSelected(app, event)

            try

                app.base.project.session.meta.module_status.quantification = true;

                % uiconfirm ask user if finished
                fig = app.UIFigure;
                msg = "Do you want to save the data?";
                title = "ICP-Base";
                selection = uiconfirm(fig,msg,title, ...
                    "Options",["Save","Don't save","Cancel"], ...
                    "DefaultOption",1,"CancelOption",3);
                switch selection
                    case 'Save'
                        [app.project_id, app.session_id] = save_to_base(app.sourceapp, app.project_id, app.base.project.session, app.session_id, app.base.project_name, settings);
                        delete(app)
                    case "Don't save"
                        delete(app)
                    case 'Cancel'
                        return
                end

            catch ME
                return
            end      
            
        end

        % Value changed function: PrimaryReferenceMaterialDropDown
        function PrimaryReferenceMaterialDropDownValueChanged(app, event)
            app.selected_PRM = app.PrimaryReferenceMaterialDropDown.Value;
            update_quantification_module_GUI(app)
        end

        % Value changed function: BracketIdDropDown
        function BracketIdDropDownValueChanged(app, event)
            app.selected_bracket_id = app.BracketIdDropDown.Value;
            update_quantification_module_GUI(app)
        end

        % Cell edit callback: InclusionTable
        function InclusionTableCellEdit(app, event)
            changes = check_changes(app);
            if changes == false
                return
            end
            app.editstatus = true;
            
            indices = event.Indices;
            newData = event.NewData;

            % Validate newData
            if ischar(newData) || isstring(newData)
                if ~startsWith(lower(newData), 'from')
                    % Try to convert to number
                    try
                        newData = str2double(newData);
                        if isnan(newData)
                            newData = [];
                        end
                    catch
                        newData = [];
                    end
                end
            elseif ~isnumeric(newData)
                newData = [];
            elseif isnan(newData)
                newData = [];
            end

            if strcmp(event.PreviousData,'----------------')
                newData = event.PreviousData;
                app.InclusionTable.Data.(indices(2))(indices(1))={newData};
            else
                for spot_idx = app.selected_spot'
                    app.base.project.session.spot.internal_standard_mass_fraction_inclusion{app.BracketIdDropDown.Value, app.selected_PRM}{spot_idx} = newData;
                end
            end

            update_quantification_module_GUI(app)

        end

        % Selection changed function: InclusionTable
        function InclusionTableSelectionChanged(app, event)
            app.selected_spot = unique(app.InclusionTable.Selection(:,1));
            app.selected_table = 'InclusionTable';

            update_quantification_module_GUI(app)
        end

        % Button pushed function: ClassificationButton, DriftButton, 
        % ...and 3 other components
        function ImportButtonPushed(app, event)
            try
                if app.editstatus == true
                    % uiconfirm ask user if finished
                    fig = app.UIFigure;
                    msg = "Do you want to proceed and save the data?";
                    title = "ICP-Base";
                    selection = uiconfirm(fig,msg,title, ...
                        "Options",["Save","Don't save","Cancel"], ...
                        "DefaultOption",1,"CancelOption",3);
                else
                    selection = 'Save';
                end

                switch selection
                    case 'Save'

                        [app.project_id, app.session_id] = save_to_base(app.sourceapp, app.project_id, app.base.project.session, app.session_id, app.base.project_name, settings);

                        switch event.Source.Parent.Parent.Parent.CallbackObject.Text
                            case 'Import'
                                Import_Module(app.sourceapp, app.base.project_name, app.project_id, app.session_id)
                            case 'Interval'
                                Interval_Module(app.sourceapp, app.base.project_name, app.project_id, app.session_id)
                            case 'Classification'
                                Classification_Module(app.sourceapp, app.base.project_name, app.project_id, app.session_id)
                            case 'Drift'
                                Drift_Module(app.sourceapp, app.base.project_name, app.project_id, app.session_id)
                            case 'Quantification'
                                Quantification_Module(app.sourceapp, app.base.project_name, app.project_id, app.session_id)
                            case 'Results'
                                Results_Module(app.sourceapp, app.base.project_name, app.project_id, app.session_id)
                        end

                        delete(app)

                    case "Don't save"

                        switch event.Source.Parent.Parent.Parent.CallbackObject.Text
                            case 'Import'
                                Import_Module(app.sourceapp, app.base.project_name, app.project_id, app.session_id)
                            case 'Interval'
                                Interval_Module(app.sourceapp, app.base.project_name, app.project_id, app.session_id)
                            case 'Classification'
                                Classification_Module(app.sourceapp, app.base.project_name, app.project_id, app.session_id)
                            case 'Drift'
                                Drift_Module(app.sourceapp, app.base.project_name, app.project_id, app.session_id)
                            case 'Quantification'
                                Quantification_Module(app.sourceapp, app.base.project_name, app.project_id, app.session_id)
                            case 'Results'
                                Results_Module(app.sourceapp, app.base.project_name, app.project_id, app.session_id)
                        end

                        delete(app)

                    case 'Cancel'

                        return
                end

            catch ME
                return
            end
        end

        % Button pushed function: ShowDwelltimesButton
        function ShowDwelltimesButtonPushed(app, event)
            
            % Create a new figure window
            fig = uifigure('Name', 'Dwell Times', 'Position', [100 100 1200 100]);

            % Create a uitable
            uit = uitable(fig);
            uit.Position = [20 20 1160 70];

            % Get the dwell times data
            dwellData = app.base.project.session.dwelltimes;

            % Set the data and column names
            uit.Data = table2array(dwellData);
            uit.ColumnName = dwellData.Properties.VariableNames;

            % Make table editable
            uit.ColumnEditable = true(1, width(dwellData));

            % Add cell edit callback to validate and update values
            uit.CellEditCallback = @(src, event) updateDwellTime(app, src, event);

            % Adjust column widths
            uit.ColumnWidth = repmat({50}, 1, width(dwellData));


        end

        % Value changed function: StringCompareInputEditField
        function StringCompareInputEditFieldValueChanged(app, event)

        end

        % Button pushed function: ChooseButton
        function ChooseButtonPushed(app, event)
           selected_string = app.SpotnamecontainsEditField.Value;

            % Initialize an empty array to store the indices of the selected spots
            selected_spot_indices = [];

            % Iterate through the spot names
            for i = 1:length(app.base.project.session.spotname)
                % Check if the spot name contains the selected string
                if contains(app.base.project.session.spotname{i}, selected_string) && ismember(i, unique(app.all_white_cells(:,1)))
                    % If it does, add the index to the selected_spot_indices array
                    selected_spot_indices(end+1) = i; %#ok
                end
            end

            % Store the selected spot indices in the app property
            app.selected_spot = selected_spot_indices;

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.BaseMenu = uimenu(app.UIFigure);
            app.BaseMenu.Text = 'Base';
            app.SaveSessiontoBaseandExitMenu = uimenu(app.BaseMenu);
            app.SaveSessiontoBaseandExitMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveSessiontoBaseandExitMenuSelected, true);
            app.SaveSessiontoBaseandExitMenu.Text = 'Save Session to Base and Exit';
            app.MatrixTable = uitable(app.UIFigure);
            app.MatrixTable.ColumnName = '';
            app.MatrixTable.RowName = {};
            app.MatrixTable.ColumnEditable = [false false false true true true];
            app.MatrixTable.CellEditCallback = createCallbackFcn(app, @MatrixTableCellEdit, true);
            app.MatrixTable.SelectionChangedFcn = createCallbackFcn(app, @MatrixTableSelectionChanged, true);
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.AnalyteListBox = uilistbox(app.UIFigure);
            app.AnalyteListBox.Items = {};
            app.AnalyteListBox.ValueChangedFcn = createCallbackFcn(app, @AnalyteListBoxValueChanged, true);
            app.AnalyteListBox.Visible = 'off';
            app.AnalyteListBox.Value = {};
            app.fromPRMListBox = uilistbox(app.UIFigure);
            app.fromPRMListBox.Items = {};
            app.fromPRMListBox.ValueChangedFcn = createCallbackFcn(app, @fromPRMListBoxValueChanged, true);
            app.fromPRMListBox.Visible = 'off';
            app.fromPRMListBox.Value = {};
            app.ProceedtoResultsModuleButton = uibutton(app.UIFigure, 'push');
            app.ProceedtoResultsModuleButton.ButtonPushedFcn = createCallbackFcn(app, @ProceedtoResultsModuleButtonPushed, true);
            app.ProceedtoResultsModuleButton.Text = 'Proceed to Results Module';
            app.Panel = uipanel(app.UIFigure);
            app.ApplyButton = uibutton(app.Panel, 'push');
            app.ApplyButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyButtonPushed, true);
            app.ApplyButton.Text = 'Apply';
            app.ContainsLabel = uilabel(app.Panel);
            app.ContainsLabel.Text = 'Spotname contains:';
            app.SpotnamecontainsEditField = uieditfield(app.Panel, 'text');
            app.SpotnamecontainsEditField.ValueChangedFcn = createCallbackFcn(app, @SpotnamecontainsEditFieldValueChanged, true);
            app.StringCompareInputEditField = uieditfield(app.Panel, 'numeric');
            app.StringCompareInputEditField.Limits = [0 Inf];
            app.StringCompareInputEditField.AllowEmpty = 'on';
            app.StringCompareInputEditField.ValueChangedFcn = createCallbackFcn(app, @StringCompareInputEditFieldValueChanged, true);
            app.StringCompareDropDown = uidropdown(app.Panel);
            app.StringCompareDropDown.Items = {''};
            app.StringCompareDropDown.Value = '';
            app.ChooseButton = uibutton(app.Panel, 'push');
            app.ChooseButton.ButtonPushedFcn = createCallbackFcn(app, @ChooseButtonPushed, true);
            app.ChooseButton.Text = 'Choose';
            app.InclusionTable = uitable(app.UIFigure);
            app.InclusionTable.ColumnName = '';
            app.InclusionTable.RowName = {};
            app.InclusionTable.ColumnEditable = [false false false true];
            app.InclusionTable.CellEditCallback = createCallbackFcn(app, @InclusionTableCellEdit, true);
            app.InclusionTable.SelectionChangedFcn = createCallbackFcn(app, @InclusionTableSelectionChanged, true);
            app.InclusionTable.Visible = 'off';
            app.PrimaryReferenceMaterialDropDownLabel = uilabel(app.UIFigure);
            app.PrimaryReferenceMaterialDropDownLabel.Text = 'Primary Reference Material';
            app.PrimaryReferenceMaterialDropDown = uidropdown(app.UIFigure);
            app.PrimaryReferenceMaterialDropDown.Items = {};
            app.PrimaryReferenceMaterialDropDown.ValueChangedFcn = createCallbackFcn(app, @PrimaryReferenceMaterialDropDownValueChanged, true);
            app.PrimaryReferenceMaterialDropDown.Value = {};
            app.BracketIdDropDownLabel = uilabel(app.UIFigure);
            app.BracketIdDropDownLabel.Text = 'Bracket Id';
            app.BracketIdDropDown = uidropdown(app.UIFigure);
            app.BracketIdDropDown.Items = {};
            app.BracketIdDropDown.ValueChangedFcn = createCallbackFcn(app, @BracketIdDropDownValueChanged, true);
            app.BracketIdDropDown.Value = {};
            app.ProgressPanel = uipanel(app.UIFigure);
            app.IntervalButton = uibutton(app.ProgressPanel, 'push');
            app.IntervalButton.ButtonPushedFcn = createCallbackFcn(app, @ImportButtonPushed, true);
            app.IntervalButton.Text = 'Interval';
            app.ImportButton = uibutton(app.ProgressPanel, 'push');
            app.ImportButton.ButtonPushedFcn = createCallbackFcn(app, @ImportButtonPushed, true);
            app.ImportButton.Text = 'Import';
            app.ClassificationButton = uibutton(app.ProgressPanel, 'push');
            app.ClassificationButton.ButtonPushedFcn = createCallbackFcn(app, @ImportButtonPushed, true);
            app.ClassificationButton.Text = 'Classification';
            app.DriftButton = uibutton(app.ProgressPanel, 'push');
            app.DriftButton.ButtonPushedFcn = createCallbackFcn(app, @ImportButtonPushed, true);
            app.DriftButton.Text = 'Drift';
            app.QuantificationButton = uibutton(app.ProgressPanel, 'push');
            app.QuantificationButton.Text = 'Quantification';
            app.ResultsButton = uibutton(app.ProgressPanel, 'push');
            app.ResultsButton.ButtonPushedFcn = createCallbackFcn(app, @ImportButtonPushed, true);
            app.ResultsButton.Text = 'Results';
            app.ShowDwelltimesButton = uibutton(app.UIFigure, 'push');
            app.ShowDwelltimesButton.ButtonPushedFcn = createCallbackFcn(app, @ShowDwelltimesButtonPushed, true);
            app.ShowDwelltimesButton.Text = 'Show Dwelltimes';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Quantification_Module(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end