classdef Results_Module < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        BaseMenu                 matlab.ui.container.Menu
        SavetoBaseandExitMenu    matlab.ui.container.Menu
        TabGroup                 matlab.ui.container.TabGroup
        ResultsTab               matlab.ui.container.Tab
        CreateOutputTableButton  matlab.ui.control.Button
        BracketIdDropDown        matlab.ui.control.DropDown
        BracketIdDropDownLabel   matlab.ui.control.Label
        PrimaryReferenceMaterialDropDown  matlab.ui.control.DropDown
        PrimaryReferenceMaterialDropDownLabel  matlab.ui.control.Label
        HostInclusionDropDown    matlab.ui.control.DropDown
        ResultsTable             matlab.ui.control.Table
        QualityControlTab        matlab.ui.container.Tab
        EndDataReductionButton   matlab.ui.control.Button
        ProgressPanel            matlab.ui.container.Panel
        ResultsButton            matlab.ui.control.Button
        QuantificationButton     matlab.ui.control.Button
        DriftButton              matlab.ui.control.Button
        ClassificationButton     matlab.ui.control.Button
        ImportButton             matlab.ui.control.Button
        IntervalButton           matlab.ui.control.Button
    end

    properties (Access = public)
        base
        project_id
        session_id
    end

    properties (Access = private)
        sourceapp
        selected_PRM
        selected_bracket_id
        selected_host_inclusion

        editstatus = false
    end
       

    methods (Access = public)


        function update_progress_buttons(app)

            status = app.base.project.session.meta.module_status;

            green = [204/255, 1, 204/255];
            orange = [1, 229/255, 204/255];
            red = [1, 204/255, 204/255];

            if isfield(status, 'import') && status.import == true
                app.ImportButton.Enable = "off";
                app.ImportButton.BackgroundColor = green;
            else
                app.ImportButton.Enable = "off";
                app.ImportButton.BackgroundColor = red;
            end

            if isfield(status, 'interval') && status.interval == true
                app.IntervalButton.Enable = "on";
                app.IntervalButton.BackgroundColor = green;
            else
                app.IntervalButton.Enable = "off";
                app.IntervalButton.BackgroundColor = red;
            end

            if isfield(status, 'classification') && status.classification == true
                app.ClassificationButton.Enable = "on";
                app.ClassificationButton.BackgroundColor = green;
            else
                app.ClassificationButton.Enable = "off";
                app.ClassificationButton.BackgroundColor = red;
            end

            if isfield(status, 'drift') && status.drift == true
                app.DriftButton.Enable = "on";
                app.DriftButton.BackgroundColor = green;
            else
                app.DriftButton.Enable = "off";
                app.DriftButton.BackgroundColor = red;
            end

            if isfield(status, 'quantification') && status.quantification == true
                app.QuantificationButton.Enable = "on";
                app.QuantificationButton.BackgroundColor = green;
            else
                app.QuantificationButton.Enable = "off";
                app.QuantificationButton.BackgroundColor = red;
            end

            app.ResultsButton.Enable = "on";
            app.ResultsButton.BackgroundColor = orange;
            app.ResultsButton.FontWeight = 'bold';

        end


        %%%%%%%%%%%%%%%%%%%%%%%%%% GUI %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        function update_results_module_GUI(app)

            % Get unique reference materials
            uniquePRMs = unique(app.base.project.session.spot.referencematerial);
            validPRMs = uniquePRMs(~strcmp(uniquePRMs, 'None'));

            % Populate PRM dropdown
            app.PrimaryReferenceMaterialDropDown.Items = validPRMs;
            app.PrimaryReferenceMaterialDropDown.ItemsData = 1:numel(app.PrimaryReferenceMaterialDropDown.Items);

            % Get selected reference material and find matching indices
            selectedRM = validPRMs(app.PrimaryReferenceMaterialDropDown.Value);
            rmIndices = strcmp(app.base.project.session.spot.referencematerial, selectedRM);

            % Filter bracket IDs for selected reference material
            filteredBracketIds = app.base.project.session.spot.bracket_id(rmIndices);
            uniqueBracketIds = unique(filteredBracketIds);
            uniqueBracketIds = uniqueBracketIds(uniqueBracketIds ~= 0);

            % Update bracket ID dropdown
            app.BracketIdDropDown.Items = string(uniqueBracketIds);
            app.BracketIdDropDown.ItemsData = uniqueBracketIds;

            % at startup the selected_PRM is empty, make it 1 so that
            % something is plotted anyways
            if isempty(app.selected_PRM)
                app.selected_PRM = 1;
            end
            % Set default bracket_id if empty - use first available bracket_id
            if isempty(app.selected_bracket_id)
                app.selected_bracket_id = uniqueBracketIds(1);
                app.BracketIdDropDown.Value = uniqueBracketIds(1);
            end

            if isequal(app.base.project.session.meta.reductiontype, 'Inclusion')
                app.HostInclusionDropDown.Items = ["Host", "Inclusion"];
                app.HostInclusionDropDown.ItemsData = app.HostInclusionDropDown.Items;
            elseif isequal(app.base.project.session.meta.reductiontype, 'Matrix')
                app.HostInclusionDropDown.Visible = 'off';
                app.HostInclusionDropDown.Enable = 'off';
                app.HostInclusionDropDown.Items = "Host";
                app.HostInclusionDropDown.ItemsData = app.HostInclusionDropDown.Items;
            end

            if isempty(app.selected_host_inclusion)
                app.selected_host_inclusion = app.HostInclusionDropDown.ItemsData{1};
            end

            %%%%%%%%%%%%%%%%%%%%
            update_results_table(app)
            update_progress_buttons(app)


        end


        function update_results_table(app)

            % preallocate the columns
            spotnames = app.base.project.session.spotname(:);  % ensure column vector
            spotNumbers = (1:numel(spotnames))';  % create column vector of numbers

            switch app.selected_host_inclusion
                case 'Host'
                    compositions = app.base.project.session.spot.matrix_composition{app.BracketIdDropDown.Value, app.selected_PRM};
                    limits_of_detection = app.base.project.session.spot.matrix_limits_of_detection{app.BracketIdDropDown.Value, app.selected_PRM};
                case 'Inclusion'
                    compositions = app.base.project.session.spot.inclusion_composition{app.BracketIdDropDown.Value, app.selected_PRM};
                    limits_of_detection = app.base.project.session.spot.inclusion_limits_of_detection{app.BracketIdDropDown.Value, app.selected_PRM};
            end

            % Generate initial table with spot numbers and names
            T = table(spotNumbers, spotnames, 'VariableNames', {'#', 'Spotname'});

            % Convert tables to arrays for manipulation
            comp_array = table2array(compositions);
            lod_array = table2array(limits_of_detection);

            % Create logical mask for values below detection limit
            below_lod = comp_array < lod_array;

            % Create cell array for display values
            display_values = num2cell(comp_array);

            % Format detection limit values where needed
            display_values(below_lod) = arrayfun(@(x) ['<' num2str(x)], lod_array(below_lod), 'UniformOutput', false);

            % Convert back to table with original variable names
            display_compositions = array2table(display_values, 'VariableNames', compositions.Properties.VariableNames);

            % Combine with modified composition data
            T = [T, display_compositions];

            % Create mask for non-zero rows (check only composition columns)
            non_zero_mask = any(comp_array ~= 0, 2);

            % Filter table to keep only non-zero rows
            T = T(non_zero_mask, :);

            % Update the displayed table
            app.ResultsTable.Data = T;
            app.ResultsTable.ColumnName = T.Properties.VariableNames;
        end


        % Preliminary Table Outputs
        % function preliminary_matrix_output_table(app, outputpath)
        % 
        %     if isfield(app.base.project.session.spot, 'matrix_composition')
        %         % Get the matrix composition data and spotnames
        %         matrix_composition = app.base.project.session.spot.matrix_composition;
        %         spotnames = app.base.project.session.spotname;
        % 
        %         uniquePRMs = unique(app.base.project.session.spot.referencematerial);
        %         validPRMs = uniquePRMs(~strcmp(uniquePRMs, 'None'));
        % 
        %         % get the indices of all rows that are not all 0
        %         unk_rows_idx = find(any(table2array(matrix_composition{app.BracketIdDropDown.Value, app.selected_PRM}), 2));
        % 
        %         % Iterate through each bracket (rows) and PRM (columns)
        %         for bracket = 1:size(matrix_composition, 1)
        %             for prm = 1:size(matrix_composition, 2)
        %                 % Get current table
        %                 current_table = matrix_composition{bracket, prm};
        % 
        %                 % Filter table to only include rows from unk_rows_idx
        %                 current_table = current_table(unk_rows_idx,:);
        % 
        %                 % Create new table with Nr. and Spotnames columns
        %                 Nr = unk_rows_idx(:);  % spot numbers
        %                 Spotnames = spotnames(unk_rows_idx);
        % 
        %                 % Combine all columns
        %                 inclusion_table = addvars(current_table, Nr, Spotnames, 'Before', 1);
        % 
        %                 % Create sheet name combining PRM name and bracket ID
        %                 sheet_name = sprintf('%s bracket_%d', char(validPRMs(prm)), bracket);
        % 
        %                 % Write filtered table to its own sheet in the Excel file
        %                 writetable(inclusion_table, outputpath, 'Sheet', sheet_name, 'WriteRowNames', false);
        %             end
        %         end
        %     end
        % end
        % 
        % 
        % function preliminary_inclusion_output_table(app, outputpath)
        % 
        %     if isequal(app.base.project.session.meta.reductiontype, 'Inclusion') && isfield(app.base.project.session.spot, 'inclusion_composition')
        %         % Get the matrix composition data and spotnames
        %         inclusion_composition = app.base.project.session.spot.inclusion_composition;
        %         spotnames = app.base.project.session.spotname;
        % 
        %         uniquePRMs = unique(app.base.project.session.spot.referencematerial);
        %         validPRMs = uniquePRMs(~strcmp(uniquePRMs, 'None'));
        % 
        %         % get the indices of all rows that are not all 0
        %         unk_rows_idx = find(any(table2array(inclusion_composition{app.BracketIdDropDown.Value, app.selected_PRM}), 2));
        % 
        %         % Iterate through each bracket (rows) and PRM (columns)
        %         for bracket = 1:size(inclusion_composition, 1)
        %             for prm = 1:size(inclusion_composition, 2)
        %                 % Get current table
        %                 current_table = inclusion_composition{bracket, prm};
        % 
        %                 % Filter table to only include rows from unk_rows_idx
        %                 current_table = current_table(unk_rows_idx,:);
        % 
        %                 % Create new table with Nr. and Spotnames columns
        %                 Nr = unk_rows_idx(:);  % spot numbers
        %                 Spotnames = spotnames(unk_rows_idx);
        % 
        %                 % Combine all columns
        %                 inclusion_table = addvars(current_table, Nr, Spotnames, 'Before', 1);
        % 
        %                 % Create sheet name combining PRM name and bracket ID
        %                 sheet_name = sprintf('%s bracket_%d', char(validPRMs(prm)), bracket);
        % 
        %                 % Write filtered table to its own sheet in the Excel file
        %                 writetable(inclusion_table, outputpath, 'Sheet', sheet_name, 'WriteRowNames', false);
        %             end
        %         end
        % 
        %     end
        % 
        % 
        % end
        % 

        function export_displayed_table(app, outputpath)
            % Get the currently displayed table
            displayed_table = app.ResultsTable.Data;

            % Get column names
            column_names = app.ResultsTable.ColumnName;

            % Convert to table if it's not already
            if ~istable(displayed_table)
                displayed_table = array2table(displayed_table, 'VariableNames', column_names);
            end

            % Create sheet name based on current selection
            sheet_name = sprintf('%s_%s_bracket_%d', ...
                app.selected_host_inclusion{1}, ...
                char(unique(app.base.project.session.spot.referencematerial(app.selected_PRM))), ...
                app.BracketIdDropDown.Value);

            % Write the table to Excel
            writetable(displayed_table, outputpath, 'Sheet', sheet_name, 'WriteRowNames', false);
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, sourceapp, project_name, project_id, session_id, data)
            
            if exist("sourceapp","var")
                app.sourceapp = sourceapp;
            end% input argument of startupfcn

            if exist("project_name", "var")
                app.base.project_name = project_name;
            end

            %% when the show session button is pressed & when the Interval_Module is called from another Module
            if  exist("data", "var") % e.g. for API call

                app.base.project.session =  app.sourceapp.base(project_id).project(session_id).session;
                app.base.project.session.meta.module_status.results = true;


            elseif exist("project_id","var") && exist("session_id","var") % coming from the DIQES base

                app.base.project.session =  app.sourceapp.base(project_id).project(session_id).session;
                app.base.project.session.meta.module_status.results = true;

            end

            app.project_id = project_id;
            app.session_id = session_id;
            app.UIFigure.Name = [char(app.base.project_name) ' - ' char(app.base.project.session.session_name)]; %string(app.base.project.session.session_name);

            update_results_module_GUI(app)
        end

        % Menu selected function: SavetoBaseandExitMenu
        function SavetoBaseandExitMenuSelected(app, event)
            
            try

                app.base.project.session.meta.module_status.results = true;

                if app.editstatus == true
                    % uiconfirm ask user if finished
                    fig = app.UIFigure;
                    msg = "Do you want to proceed and save the data?";
                    title = "ICP-Base";
                    selection = uiconfirm(fig,msg,title, ...
                        "Options",["Save","Don't save","Cancel"], ...
                        "DefaultOption",1,"CancelOption",3);
                else
                    selection = 'Save';
                end

                switch selection
                    case 'Save'
                        [app.project_id, app.session_id] = save_to_base(app.sourceapp, app.project_id, app.base.project.session, app.session_id, app.base.project_name, settings);
                        delete(app)
                    case "Don't save"
                        delete(app)
                    case 'Cancel'
                        return
                end

            catch ME
                return
            end      
            
        end

        % Value changed function: PrimaryReferenceMaterialDropDown
        function PrimaryReferenceMaterialDropDownValueChanged(app, event)
            app.selected_PRM = app.PrimaryReferenceMaterialDropDown.Value;

            update_results_module_GUI(app)
        end

        % Value changed function: BracketIdDropDown
        function BracketIdDropDownValueChanged(app, event)
            app.selected_bracket_id = app.BracketIdDropDown.Value;

            update_results_module_GUI(app)
        end

        % Button pushed function: EndDataReductionButton
        function EndDataReductionButtonPushed(app, event)
                    
            try

                app.base.project.session.meta.module_status.results = true;

                if app.editstatus == true
                    % uiconfirm ask user if finished
                    fig = app.UIFigure;
                    msg = "Do you want to proceed and save the data?";
                    title = "ICP-Base";
                    selection = uiconfirm(fig,msg,title, ...
                        "Options",["Save","Don't save","Cancel"], ...
                        "DefaultOption",1,"CancelOption",3);
                else
                    selection = 'Save';
                end

                switch selection
                    case 'Save'
                        [app.project_id, app.session_id] = save_to_base(app.sourceapp, app.project_id, app.base.project.session, app.session_id, app.base.project_name, settings);
                        delete(app)
                    case "Don't save"
                        delete(app)
                    case 'Cancel'
                        return
                end

            catch ME
                return
            end      


        end

        % Value changed function: HostInclusionDropDown
        function HostInclusionDropDownValueChanged(app, event)
            app.selected_host_inclusion = app.HostInclusionDropDown.Value;
            
            update_results_module_GUI(app)
        end

        % Button pushed function: ClassificationButton, DriftButton, 
        % ...and 2 other components
        function QuantificationButtonPushed(app, event)
             try

                if app.editstatus == true
                    % uiconfirm ask user if finished
                    fig = app.UIFigure;
                    msg = "Do you want to proceed and save the data?";
                    title = "ICP-Base";
                    selection = uiconfirm(fig,msg,title, ...
                        "Options",["Save","Don't save","Cancel"], ...
                        "DefaultOption",1,"CancelOption",3);
                else
                    selection = 'Save';
                end

                switch selection
                    case 'Save'
                        
                        [app.project_id, app.session_id] = save_to_base(app.sourceapp, app.project_id, app.base.project.session, app.session_id, app.base.project_name, settings);

                        switch event.Source.Parent.Parent.Parent.CallbackObject.Text
                            case 'Import'
                                Import_Module(app.sourceapp, app.base.project_name, app.project_id, app.session_id)
                            case 'Interval'
                                Interval_Module(app.sourceapp, app.base.project_name, app.project_id, app.session_id)
                            case 'Classification'
                                Classification_Module(app.sourceapp, app.base.project_name, app.project_id, app.session_id)
                            case 'Drift'
                                Drift_Module(app.sourceapp, app.base.project_name, app.project_id, app.session_id)
                            case 'Quantification'
                                Quantification_Module(app.sourceapp, app.base.project_name, app.project_id, app.session_id)
                            case 'Results'
                                Results_Module(app.sourceapp, app.base.project_name, app.project_id, app.session_id)
                        end

                        delete(app)

                    case "Don't save"

                        switch event.Source.Parent.Parent.Parent.CallbackObject.Text
                            case 'Import'
                                Import_Module(app.sourceapp, app.base.project_name, app.project_id, app.session_id)
                            case 'Interval'
                                Interval_Module(app.sourceapp, app.base.project_name, app.project_id, app.session_id)
                            case 'Classification'
                                Classification_Module(app.sourceapp, app.base.project_name, app.project_id, app.session_id)
                            case 'Drift'
                                Drift_Module(app.sourceapp, app.base.project_name, app.project_id, app.session_id)
                            case 'Quantification'
                                Quantification_Module(app.sourceapp, app.base.project_name, app.project_id, app.session_id)
                            case 'Results'
                                Results_Module(app.sourceapp, app.base.project_name, app.project_id, app.session_id)
                        end

                        delete(app)

                    case 'Cancel'

                        return
                end

            catch ME
                return
            end
        end

        % Button pushed function: CreateOutputTableButton
        function CreateOutputTableButtonPushed(app, event)
            %  [filename, pathname] = uiputfile('*.xlsx', 'Save Excel file');
            % app.base.project.session.outputpath = fullfile(pathname, filename);
            % if isequal(filename, 0) || isequal(pathname, 0)
            %     return; % User canceled file selection
            % end

            outputpath = 'C:\Users\Sebastian\OneDrive - Universitaet Bern\PhD\miqasa\Test Data\Output Test\Almirez.xlsx';
            %app.base.project.session.outputpath = fullfile(pathname, filename);
            export_displayed_table(app, outputpath)


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.BaseMenu = uimenu(app.UIFigure);
            app.BaseMenu.Text = 'Base';
            app.SavetoBaseandExitMenu = uimenu(app.BaseMenu);
            app.SavetoBaseandExitMenu.MenuSelectedFcn = createCallbackFcn(app, @SavetoBaseandExitMenuSelected, true);
            app.SavetoBaseandExitMenu.Text = 'Save to Base and Exit';
            app.ProgressPanel = uipanel(app.UIFigure);
            app.IntervalButton = uibutton(app.ProgressPanel, 'push');
            app.IntervalButton.ButtonPushedFcn = createCallbackFcn(app, @QuantificationButtonPushed, true);
            app.IntervalButton.Text = 'Interval';
            app.ImportButton = uibutton(app.ProgressPanel, 'push');
            app.ImportButton.Text = 'Import';
            app.ClassificationButton = uibutton(app.ProgressPanel, 'push');
            app.ClassificationButton.ButtonPushedFcn = createCallbackFcn(app, @QuantificationButtonPushed, true);
            app.ClassificationButton.Text = 'Classification';
            app.DriftButton = uibutton(app.ProgressPanel, 'push');
            app.DriftButton.ButtonPushedFcn = createCallbackFcn(app, @QuantificationButtonPushed, true);
            app.DriftButton.Text = 'Drift';
            app.QuantificationButton = uibutton(app.ProgressPanel, 'push');
            app.QuantificationButton.ButtonPushedFcn = createCallbackFcn(app, @QuantificationButtonPushed, true);
            app.QuantificationButton.Text = 'Quantification';
            app.ResultsButton = uibutton(app.ProgressPanel, 'push');
            app.ResultsButton.Text = 'Results';
            app.EndDataReductionButton = uibutton(app.UIFigure, 'push');
            app.EndDataReductionButton.ButtonPushedFcn = createCallbackFcn(app, @EndDataReductionButtonPushed, true);
            app.EndDataReductionButton.Text = 'End Data Reduction';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.ResultsTab = uitab(app.TabGroup);
            app.ResultsTab.Title = 'Results';
            app.ResultsTable = uitable(app.ResultsTab);
            app.ResultsTable.ColumnName = '';
            app.ResultsTable.RowName = {};
            app.HostInclusionDropDown = uidropdown(app.ResultsTab);
            app.HostInclusionDropDown.Items = {};
            app.HostInclusionDropDown.ValueChangedFcn = createCallbackFcn(app, @HostInclusionDropDownValueChanged, true);
            app.HostInclusionDropDown.Value = {};
            app.PrimaryReferenceMaterialDropDownLabel = uilabel(app.ResultsTab);
            app.PrimaryReferenceMaterialDropDownLabel.Text = 'Primary Reference Material';
            app.PrimaryReferenceMaterialDropDown = uidropdown(app.ResultsTab);
            app.PrimaryReferenceMaterialDropDown.Items = {};
            app.PrimaryReferenceMaterialDropDown.ValueChangedFcn = createCallbackFcn(app, @PrimaryReferenceMaterialDropDownValueChanged, true);
            app.PrimaryReferenceMaterialDropDown.Value = {};
            app.BracketIdDropDownLabel = uilabel(app.ResultsTab);
            app.BracketIdDropDownLabel.Text = 'Bracket Id';
            app.BracketIdDropDown = uidropdown(app.ResultsTab);
            app.BracketIdDropDown.Items = {};
            app.BracketIdDropDown.ValueChangedFcn = createCallbackFcn(app, @BracketIdDropDownValueChanged, true);
            app.BracketIdDropDown.Value = {};
            app.CreateOutputTableButton = uibutton(app.ResultsTab, 'push');
            app.CreateOutputTableButton.ButtonPushedFcn = createCallbackFcn(app, @CreateOutputTableButtonPushed, true);
            app.CreateOutputTableButton.Text = 'Create Output Table';
            app.QualityControlTab = uitab(app.TabGroup);
            app.QualityControlTab.Title = 'Quality Control';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Results_Module(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end