classdef HistogramFilterModule < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        HistogramFilterModuleFigure  matlab.ui.Figure
        GridLayout                   matlab.ui.container.GridLayout
        DropDown                     matlab.ui.control.DropDown
        DropDownLabel                matlab.ui.control.Label
        ResetLinesButton             matlab.ui.control.Button
        Button_2                     matlab.ui.control.Button
        Button                       matlab.ui.control.Button
        EditField_climMax            matlab.ui.control.NumericEditField
        EditField_climMin            matlab.ui.control.NumericEditField
        FigHistLive                  matlab.ui.control.UIAxes
    end


    properties (Access = private)
        InputTable % Description
        mainapp
 
        update_fun
        reset_value=false
        climits
        hVerticalLines
        hLineToDrag % Description
    end

    methods (Access = private)

        function  update_FigHistLive(app)
            cla(app.FigHistLive)

            Values=app.InputTable.Data.(char(app.DropDown.Value));
            Values=Values(app.InputTable.Data.Active);

            num_bars=50;

            [N, EDGES] = histcounts(Values(:),num_bars); %// use two-output version of hist to get values
            EDGES=linspace(EDGES(1),EDGES(end),num_bars);
            a_h=area(app.FigHistLive,EDGES,N,'FaceColor',[0.8 0.8 0.8],'LineWidth',1);
            set(a_h, 'hittest', 'off');
            %EDGES=EDGES(2:end-1);
            xlimits_test = double([min(EDGES) max(EDGES)]);
            app.FigHistLive.XLim=xlimits_test;

            if app.reset_value==true ||app.EditField_climMax.Value > max(xlimits_test) || app.EditField_climMin.Value < min(xlimits_test)
                app.climits=xlimits_test;
                app.EditField_climMin.Value=app.climits(1);
                app.EditField_climMax.Value=app.climits(2);
                app.reset_value=false;
            else
                app.climits(1)=min([app.EditField_climMin.Value app.EditField_climMax.Value]);
                app.climits(2)=max([app.EditField_climMin.Value app.EditField_climMax.Value]);
            end


            update_lines(app)

        end

        function update_lines(app)
            delete(findobj(app.FigHistLive.Children,'Type','ConstantLine'));


            app.hVerticalLines(1:2) = [xline(app.FigHistLive,app.EditField_climMax.Value,'Color','r',"LineWidth",3)...
                xline(app.FigHistLive, app.EditField_climMin.Value,'Color','r',"LineWidth",3)];
            set(app.hVerticalLines(1:2), 'hittest', 'off')

        end


        function update_dropdown(app)
            app.DropDown.Items=app.InputTable.Data.Properties.VariableNames(any(cellfun(@isnumeric,table2cell(app.InputTable.Data))));

        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, T, update_fun)
            movegui(app.HistogramFilterModuleFigure, 'center');
            if exist('update_fun','var')
                app.update_fun=update_fun;
            end

            app.InputTable=T;

            update_dropdown(app)

            app.mainapp=mainapp;
            update_FigHistLive(app)
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            region=sort(app.climits);
            selections=app.InputTable.Data.(char(app.DropDown.Value))<max(region) & app.InputTable.Data.(char(app.DropDown.Value))>min(region) & app.InputTable.Data.(char(app.DropDown.Value))>min(region);

            app.InputTable.Data.Active=selections;


            update_FigHistLive(app)
            update_dropdown(app)
            if not(isempty(app.update_fun))
                app.update_fun(app.mainapp,'filter')
            end

        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)

            app.InputTable.Data.Active=true(size(app.InputTable.Data.Active,1),1);

            update_FigHistLive(app)
            update_dropdown(app)
            if not(isempty(app.update_fun))
                app.update_fun(app.mainapp,'reset')
            end

        end

        % Button down function: FigHistLive
        function FigHistLiveButtonDown(app, event)
            for k=1:2
                xVertLineCoord = get(app.hVerticalLines(k),'Value');
                click_buffer=(max(app.FigHistLive.XLim)-min(app.FigHistLive.XLim))*0.05; %ATH Settingspanel
                if abs(event.IntersectionPoint(1) - xVertLineCoord(1)) < click_buffer
                    app.hLineToDrag = app.hVerticalLines(k);
                    break;
                end
            end

        end

        % Value changed function: EditField_climMax, EditField_climMin
        function EditField_climMaxValueChanged(app, event)

            ValueMin = min([ app.EditField_climMin.Value app.EditField_climMax.Value]);
            ValueMax = max([ app.EditField_climMin.Value app.EditField_climMax.Value]);

            if app.EditField_climMax.Value < app.EditField_climMin.Value
                app.EditField_climMin.Value=ValueMin;
                app.EditField_climMax.Value=ValueMax;
            end

            app.climits=[ValueMin ValueMax];


        end

        % Window button motion function: HistogramFilterModuleFigure
        function HistogramFilterModuleFigureWindowButtonMotion(app, event)

            if ~isempty(app.hLineToDrag)

                currentPoint   = app.FigHistLive.CurrentPoint;
                x            = currentPoint(1,1);
                y            = currentPoint(1,3);
                xlim = app.FigHistLive.XLim;
                ylim = app.FigHistLive.YLim;
                inside_FigHistLive = x >= xlim(1) & x <= xlim(2) & y >= ylim(1) & y <= ylim(2); %(val*app.clickbuffer)

                if inside_FigHistLive

                    set(app.hLineToDrag, 'Value', x);

                    xposlines=[get(app.hVerticalLines(1),'Value') get(app.hVerticalLines(2),'Value')];
                    app.EditField_climMin.Value = min(xposlines);
                    app.EditField_climMax.Value = max(xposlines);


                else
                    app.hLineToDrag = 0;
                    HistogramFilterModuleFigureWindowButtonUp(app)
                end


            end

        end

        % Window button up function: HistogramFilterModuleFigure
        function HistogramFilterModuleFigureWindowButtonUp(app, event)

            if ~isempty(app.hLineToDrag)
                app.hLineToDrag = [];


                set(app.hVerticalLines(1),'Value',app.EditField_climMax.Value)
                set(app.hVerticalLines(2),'Value',app.EditField_climMin.Value)
                app.climits=sort([app.EditField_climMax.Value app.EditField_climMin.Value]);

            end


        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
            update_FigHistLive(app)


        end

        % Button pushed function: ResetLinesButton
        function ResetLinesButtonPushed(app, event)
            if not(isempty(app.FigHistLive.XLim))
                set(app.hVerticalLines(1),'Value',app.FigHistLive.XLim(1))
                set(app.hVerticalLines(2),'Value',app.FigHistLive.XLim(2))
            end
        end

        % Drop down opening function: DropDown
        function DropDownOpening(app, event)
            update_dropdown(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.HistogramFilterModuleFigure = uifigure('Visible', 'off');
            app.HistogramFilterModuleFigure.Name = 'HistogramFilterModule';
            app.HistogramFilterModuleFigure.WindowButtonUpFcn = createCallbackFcn(app, @HistogramFilterModuleFigureWindowButtonUp, true);
            app.HistogramFilterModuleFigure.WindowButtonMotionFcn = createCallbackFcn(app, @HistogramFilterModuleFigureWindowButtonMotion, true);
            app.GridLayout = uigridlayout(app.HistogramFilterModuleFigure);
            app.FigHistLive = uiaxes(app.GridLayout);
            app.FigHistLive.ButtonDownFcn = createCallbackFcn(app, @FigHistLiveButtonDown, true);
            app.EditField_climMin = uieditfield(app.GridLayout, 'numeric');
            app.EditField_climMin.ValueDisplayFormat = '%3.4g';
            app.EditField_climMin.ValueChangedFcn = createCallbackFcn(app, @EditField_climMaxValueChanged, true);
            app.EditField_climMax = uieditfield(app.GridLayout, 'numeric');
            app.EditField_climMax.ValueDisplayFormat = '%3.4g';
            app.EditField_climMax.ValueChangedFcn = createCallbackFcn(app, @EditField_climMaxValueChanged, true);
            app.EditField_climMax.Value = 1;
            app.Button = uibutton(app.GridLayout, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Icon = 'symbols_1_Mask Selection.png';
            app.Button.Text = '';
            app.Button_2 = uibutton(app.GridLayout, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Icon = 'symbols_1_Clear Mask.png';
            app.Button_2.Text = {''; ''};
            app.ResetLinesButton = uibutton(app.GridLayout, 'push');
            app.ResetLinesButton.ButtonPushedFcn = createCallbackFcn(app, @ResetLinesButtonPushed, true);
            app.ResetLinesButton.Text = 'Reset Lines';
            app.DropDownLabel = uilabel(app.GridLayout);
            app.DropDownLabel.Text = 'Drop Down';
            app.DropDown = uidropdown(app.GridLayout);
            app.DropDown.DropDownOpeningFcn = createCallbackFcn(app, @DropDownOpening, true);
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            % Show the figure after all components are created
            app.HistogramFilterModuleFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = HistogramFilterModule(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.HistogramFilterModuleFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.HistogramFilterModuleFigure)
        end
    end
end