classdef miqasa < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MIQASUIFigure                   matlab.ui.Figure
        BaseMenu                        matlab.ui.container.Menu
        OpenBaseMenu                    matlab.ui.container.Menu
        SaveBaseMenu                    matlab.ui.container.Menu
        SaveBaseasMenu                  matlab.ui.container.Menu
        ProjectMenu                     matlab.ui.container.Menu
        NewProjectMenu                  matlab.ui.container.Menu
        OpenProjectMenu                 matlab.ui.container.Menu
        SaveProjectMenu                 matlab.ui.container.Menu
        SaveProjectasMenu               matlab.ui.container.Menu
        InfosMenu                       matlab.ui.container.Menu
        ShowSessioninResultsModuleButton  matlab.ui.control.Button
        ShowSessioninQuantificationModuleButton  matlab.ui.control.Button
        ShowSessioninDriftModuleButton  matlab.ui.control.Button
        ShowSessioninClassificationModuleButton  matlab.ui.control.Button
        DeleteProjectButton             matlab.ui.control.Button
        AddProjectButton                matlab.ui.control.Button
        ProjectTable                    matlab.ui.control.Table
        ShowSessioninIntervalModuleButton  matlab.ui.control.Button
        DeleteSessionButton             matlab.ui.control.Button
        SessionTable                    matlab.ui.control.Table
        AddSessionButton                matlab.ui.control.Button
        ShowSessioninImportModuleButton  matlab.ui.control.Button
    end


    properties (Access = public)
        base
    end

    properties (Access = private)

        LastSelectedProjectRow
    end


    methods (Access = private)

        function update_DIQES_GUI(app, project_id)

            update_project_table(app)
            update_session_table(app, project_id)
        end

        function update_project_table(app)

            % preallocate the columns
            project_name=cell(1,numel(app.base));
            %project_comment=cell(1,numel(app.base));

            % get the session names and other variables
            for i = 1:numel(app.base)
                % since the project name is now initialzed at
                % AddSessionButtonDown it will always have 'New Project'
                if isfield(app.base(i), 'project_name') && ~isempty(app.base(i).project_name)

                    project_name{i} = app.base(i).project_name;

                else
                    project_name{i}='New Project';
                end

            end

            % generate a table with the variables
            T=table((1:numel(project_name))', project_name');
            T.Properties.VariableNames={'#', 'Project Name'};

            %push this table to the displayed SessionTable and give them
            %the same column names
            app.ProjectTable.Data = T;
            app.ProjectTable.ColumnName = T.Properties.VariableNames;

        end

        % Att: this function should just be GUI stuff and it should do
        % nothing or as little as possible with the actual data structure
        function update_session_table(app, project_id)

            % preallocate the columns
            session_name=cell(1,numel(app.base(project_id).project));
            session_comment=cell(1,numel(app.base(project_id).project));
            
            % get the session names and other variables
            for i = 1:numel(app.base(project_id).project)

                session_name{i} = app.base(project_id).project(i).session.session_name;

                  if isfield(app.base(project_id).project(i).session, 'comment') && ~isempty(app.base(project_id).project(i).session.comment)
                      session_comment{i} = app.base(project_id).project(i).session.comment;
                  else
                       session_comment{i}='';
                  end
            end

            % generate a table with the variables
            T=table((1:numel(session_name))', session_name', session_comment');
            T.Properties.VariableNames={'#', 'Session Name', 'Comment'};

            %push this table to the displayed SessionTable and give them
            %the same column names
            app.SessionTable.Data = T;
            app.SessionTable.ColumnName = T.Properties.VariableNames;

        end
    
    end


    methods (Access = public)

        % this function needs to sort everything back in the base 
        function [project_id, session_id] = save_to_base(app, project_id, session, session_id, project_name, settings)

            %% sort in the session using the ids
            %% if the id is a number higher than 1, it will just go to that place

            % if I open the import module from the base, this is not needed
            % because the project_id is sent to the Import_Module
            % if the user decides to input 0 for all the project_ids 
            if isequal(project_id, 0) || isempty(project_id)
                project_id = numel(app.base) + 1;
            end

            % make an empty struct if a new project is created throught the
            % API
            if project_id > numel(app.base)
                app.base(project_id).project = struct();
            end

            % these are for sorting the sessions back into the right projects
            if numel(app.base) == 0 % completely new base initialzed by API
                session_id = 1;
            elseif isempty(fieldnames(app.base(project_id).project)) || ~isfield(app.base(project_id).project, 'session') || isempty(app.base(project_id).project)
                session_id = 1;
            elseif isequal(session_id, 0) || isempty(session_id)
                session_id = numel(app.base(project_id).project) + 1;
            end

            % save the session at the right place in the base
            app.base(project_id).project(session_id).session = session;

            %% session and project names

            app.base(project_id).project_name = project_name;

            % check if there is a session_name already in the session
            % struct, if not name it New Session
            if ~isfield(app.base(project_id).project(session_id).session, 'session_name') || isempty(app.base(project_id).project(session_id).session.session_name)
                app.base(project_id).project(session_id).session.session_name = 'New Session';
            end

            %% GUI update
            if isfield(settings, 'DIQES_GUI_update_state') && settings.DIQES_GUI_update_state == true
                update_DIQES_GUI(app, project_id)
            end

        end

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: AddSessionButton
        function AddSessionButtonPushed(app, event)
            % Att: this button should be disabled as long as there is no
            % project

            %% get the project_id as the selection of the ProjectTable

            project_id = app.LastSelectedProjectRow;

            if isempty(project_id)
                message = "Select a Project first";
                uialert(app.MIQASUIFigure,message,"Warning", ...
                    "Icon","warning");
                return
            end

            %% here the session id is assigned to the new session and then sent to the Import_Module
            % if the field session in DIQES does not yet exist, the first
            % session has id zero. when it is sent back it will change to 1
            % if not(isfield(app.base(project_id).project, 'session'))
            % 
            %     session_id = 0;
            % 
            % else % the sid is number of sessions + 1 when other sessions are added
            % 
            %     session_id = numel(app.base(project_id).project) + 1;
            % 
            % end
            % 
            % since a new session is added, there is no need to send the
            % whole project, just the ids are sent to the
            % Import_Module


            session_id = 0;
            Import_Module(app, app.base(project_id).project_name, project_id, session_id)
            % app = der Handle von DIQES,
            % was app hier ist, ist sourceapp in Import_Module

        end

        % Button pushed function: ShowSessioninImportModuleButton
        function ShowSessioninImportModuleButtonPushed(app, event)
            % Att: this button should be disabled as long as there is no
            % project

            % check if selection is not empty
            if ~isempty(app.SessionTable.Selection) && not(isequal(app.SessionTable.Selection(1),0))

                % session_id is the selection
                session_id = app.SessionTable.Selection(1);

                % the project_id is the last selected project in the
                % project table
                project_id = app.LastSelectedProjectRow;

                Import_Module(app, app.base(project_id).project_name, project_id, session_id)

            end

        end

        % Button pushed function: ShowSessioninIntervalModuleButton
        function ShowSessioninIntervalModuleButtonPushed(app, event)
            if ~isempty(app.SessionTable.Selection) && not(isequal(app.SessionTable.Selection(1),0))

                % session_id is the selection
                session_id = app.SessionTable.Selection(1);

                % the project_id is the last selected project in the
                % project table
                project_id = app.LastSelectedProjectRow;

                Interval_Module(app, app.base(project_id).project_name, project_id, session_id)

            end
        end

        % Cell edit callback: SessionTable
        function SessionTableCellEdit(app, event)
          
            % get the session table selection
            sel_row_session = app.SessionTable.Selection(1);
            sel_col_session = app.SessionTable.Selection(2);

            % this takes the LastSelectedProjectRow
            if strcmp(app.SessionTable.ColumnName{sel_col_session}, 'Session Name')
                 app.base(app.LastSelectedProjectRow).project(sel_row_session).session.session_name = event.NewData;
            elseif strcmp(app.SessionTable.ColumnName{sel_col_session}, 'Comment')
                app.base(app.LastSelectedProjectRow).project(sel_row_session).session.comment = event.NewData;
            end

        end

        % Menu selected function: SaveProjectMenu
        function SaveProjectMenuSelected(app, event)
            %% this also needs the project_id to know which project to save

            % if isprop(app,'projectpath') && not(isempty(app.base.projectpath))
            %     app.MIQASUIFigure.Name = [app.base.projectpath ' [... Saving Project]'] ;
            % 
            %     data2save.project = app.base.project;
            %     data2save.projectpath = app.base.projectpath;
            % 
            %     save(app.base.projectpath, 'data2save', '-v7.3');
            %     app.MIQASUIFigure.Name = app.base.projectpath;
            % 
            % else
            %     SaveProjectasMenuSelected(app)
            % end
            % 
            % if isprop(app,'projectpath')
            % 
            %     app.MIQASUIFigure.Name = app.base.projectpath;
            % 
            % end
        end

        % Menu selected function: SaveProjectasMenu
        function SaveProjectasMenuSelected(app, event)


            % f = figure('Renderer', 'painters', 'Position', [-100 -100 0 0]);
            % [filename, filepath] = uiputfile('*.mat', 'Save the project file:');
            % delete(f);
            % figure(app.MIQASUIFigure);
            % 
            % if not(isequal(filename,0))
            %     FileName = fullfile(filepath, filename);
            %     app.base.projectpath = FileName;
            %     app.MIQASUIFigure.Name = [app.base.projectpath ' [... Saving Project]'] ;
            % 
            %     data2save.project = app.base.project;
            %     data2save.projectpath = app.base.projectpath;
            % 
            %     save(FileName, 'data2save', '-v7.3');
            %     app.MIQASUIFigure.Name = app.base.projectpath;
            % end
        end

        % Menu selected function: OpenProjectMenu
        function OpenProjectMenuSelected(app, event)

            %     if isfield(app.base,'projectpath') && not(isempty(app.base.projectpath))
            %         [~, baseFileNameNoExt, ~] = fileparts(app.base.projectpath);
            %         projectname=[' ' baseFileNameNoExt];
            % 
            %     else
            %         projectname='';
            %     end
            %     answer = questdlg({['Do you want to save your project' projectname '?']},...
            %         '','Yes','No','Cancel','');
            %     switch answer
            %         case 'Yes'
            %             SaveProjectMenuSelected(app)
            %         case 'Cancel'
            %             return
            %     end
            % 
            % 
            % f = figure('Renderer', 'painters', 'Position', [-100 -100 0 0]);
            % [file,path]=uigetfile({'*.mat'},"MultiSelect","off", 'Open Project');
            % delete(f);
            % figure(app.MIQASUIFigure);
            % 
            % if not(isequal(file,0))
            %     data2import = load(fullfile(path,file));
            % 
            %     app.base.project = data2import.data2save.project;
            %     app.base.projectpath = data2import.data2save.projectpath;
            % else
            %     return
            % end
            % 
            % update_session_table(app)


        end

        % Button pushed function: AddProjectButton
        function AddProjectButtonPushed(app, event)
            %% initializing empty projects in the base
            if not(isfield(app.base, 'project'))

                app.base(1).project = struct();
                app.base(1).project_name = 'New Project';

            else % the entry_id

                entry_id = numel(app.base) + 1;

                app.base(entry_id).project = struct();
                app.base(entry_id).project_name = 'New Project';

            end

            update_project_table(app)
        end

        % Selection changed function: ProjectTable
        function ProjectTableSelectionChanged(app, event)
            %% updates the session table when a project is selected
            if ~isempty(app.ProjectTable.Selection)
                if not(isempty(app.ProjectTable.Selection(1)))
                    selection = app.ProjectTable.Selection(1);
                else
                    return
                end

                if not(isempty(fieldnames(app.base(selection).project))) && isfield(app.base(selection).project, 'session')
                    update_session_table(app, selection)
                else
                    app.SessionTable.Data = {};
                end
            else
                return
            end

            %% failsafe if a project is clicked on but then it is clicked 
            % somewhere in the ProjectTable where there is no project, the
            % selection gets set to [] and the conncection to the project
            % is lost
            % so the last clicked on row is always saved to always get back
            % to the project
            % Check if there is a selection in the ProjectTable
            if isempty(app.ProjectTable.Selection)
                % Use the last stored selection if the current selection is empty
                app.LastSelectedProjectRow = app.LastSelectedProjectRow;
            else
                % Update the last stored selection with the current selection
                app.LastSelectedProjectRow = app.ProjectTable.Selection(1);
            end

        end

        % Menu selected function: SaveBaseMenu
        function SaveBaseMenuSelected(app, event)

            if isfield(app.base, 'meta') && isfield(app.base(1).meta, 'basepath') && not(isempty(app.base(1).meta.basepath))
                app.MIQASUIFigure.Name = [app.base(1).meta.basepath ' [... Saving Base]'] ;

                data2save.base = app.base;
                %data2save.meta = app.meta;

                save(app.base(1).meta.basepath, 'data2save', '-v7.3');
                app.MIQASUIFigure.Name = app.base(1).meta.basepath;

            else
                SaveBaseasMenuSelected(app)
            end

            if isfield(app.base,'meta') && isfield(app.base(1).meta, 'basepath')

                app.MIQASUIFigure.Name = app.base(1).meta.basepath;

            end
        end

        % Menu selected function: OpenBaseMenu
        function OpenBaseMenuSelected(app, event)
            
                if isfield(app.base,'meta') && isfield(app.base(1).meta, 'basepath') && not(isempty(app.base(1).meta.basepath))
                    [~, baseFileNameNoExt, ~] = fileparts(app.base(1).meta.basepath);
                    basename=[' ' baseFileNameNoExt];

                else
                    basename='';
                end
                answer = questdlg({['Do you want to save this base' basename '?']},...
                    '','Yes','No','Cancel','');
                switch answer
                    case 'Yes'
                        SaveBaseMenuSelected(app)
                    case 'Cancel'
                        return
                end


            f = figure('Renderer', 'painters', 'Position', [-100 -100 0 0]);
            [file,path]=uigetfile({'*.mat'},"MultiSelect","off", 'Open Base');
            delete(f);
            figure(app.MIQASUIFigure);

            if not(isequal(file,0))
                data2import = load(fullfile(path,file));

                app.base = data2import.data2save.base;
            else
                return
            end

            update_project_table(app)

        end

        % Cell edit callback: ProjectTable
        function ProjectTableCellEdit(app, event)
            % get the session table selection
            sel_row_project = app.ProjectTable.Selection(1);
            sel_col_project = app.ProjectTable.Selection(2);


            if strcmp(app.ProjectTable.ColumnName{sel_col_project}, 'Project Name')
                 app.base(sel_row_project).project_name = event.NewData;
            end
        end

        % Menu selected function: SaveBaseasMenu
        function SaveBaseasMenuSelected(app, event)
            f = figure('Renderer', 'painters', 'Position', [-100 -100 0 0]);
            [filename, filepath] = uiputfile('*.mat', 'Save the base file:');
            delete(f);
            figure(app.MIQASUIFigure);

            if not(isequal(filename,0))
                FileName = fullfile(filepath, filename);
                app.base(1).meta.basepath = FileName;
                app.MIQASUIFigure.Name = [app.base(1).meta.basepath ' [... Saving Base]'] ;

                data2save.base = app.base;

                save(FileName, 'data2save', '-v7.3');
                app.MIQASUIFigure.Name = app.base(1).meta.basepath;
            end
        end

        % Button pushed function: ShowSessioninClassificationModuleButton
        function ShowSessioninClassificationModuleButtonPushed(app, event)
            if ~isempty(app.SessionTable.Selection) && not(isequal(app.SessionTable.Selection(1),0))

                % session_id is the selection
                session_id = app.SessionTable.Selection(1);

                % the project_id is the last selected project in the
                % project table
                project_id = app.LastSelectedProjectRow;

                Classification_Module(app, app.base(project_id).project_name, project_id, session_id)

            end
        end

        % Button pushed function: ShowSessioninDriftModuleButton
        function ShowSessioninDriftModuleButtonPushed(app, event)
            if ~isempty(app.SessionTable.Selection) && not(isequal(app.SessionTable.Selection(1),0))

                % session_id is the selection
                session_id = app.SessionTable.Selection(1);

                % the project_id is the last selected project in the
                % project table
                project_id = app.LastSelectedProjectRow;

                Drift_Module(app, app.base(project_id).project_name, project_id, session_id)

            end
        end

        % Button pushed function: ShowSessioninQuantificationModuleButton
        function ShowSessioninQuantificationModuleButtonPushed(app, event)
            if ~isempty(app.SessionTable.Selection) && not(isequal(app.SessionTable.Selection(1),0))

                % session_id is the selection
                session_id = app.SessionTable.Selection(1);

                % the project_id is the last selected project in the
                % project table
                project_id = app.LastSelectedProjectRow;

                Quantification_Module(app, app.base(project_id).project_name, project_id, session_id)

            end
        end

        % Button pushed function: DeleteProjectButton
        function DeleteProjectButtonPushed(app, event)
            if ~isempty(app.ProjectTable.Selection)
                %% deleting selected project
                selection = app.ProjectTable.Selection(1);
                app.base(selection) = [];
            else
                return
            end

            update_project_table(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MIQASUIFigure = uifigure('Visible', 'off');
            app.MIQASUIFigure.Name = 'MIQAS';
            app.BaseMenu = uimenu(app.MIQASUIFigure);
            app.BaseMenu.Text = 'Base';
            app.OpenBaseMenu = uimenu(app.BaseMenu);
            app.OpenBaseMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenBaseMenuSelected, true);
            app.OpenBaseMenu.Text = 'Open Base';
            app.SaveBaseMenu = uimenu(app.BaseMenu);
            app.SaveBaseMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveBaseMenuSelected, true);
            app.SaveBaseMenu.Text = 'Save Base';
            app.SaveBaseasMenu = uimenu(app.BaseMenu);
            app.SaveBaseasMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveBaseasMenuSelected, true);
            app.SaveBaseasMenu.Text = 'Save Base as';
            app.ProjectMenu = uimenu(app.MIQASUIFigure);
            app.ProjectMenu.Text = 'Project';
            app.NewProjectMenu = uimenu(app.ProjectMenu);
            app.NewProjectMenu.Text = 'New Project';
            app.OpenProjectMenu = uimenu(app.ProjectMenu);
            app.OpenProjectMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenProjectMenuSelected, true);
            app.OpenProjectMenu.Text = 'Open Project';
            app.SaveProjectMenu = uimenu(app.ProjectMenu);
            app.SaveProjectMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveProjectMenuSelected, true);
            app.SaveProjectMenu.Text = 'Save Project';
            app.SaveProjectasMenu = uimenu(app.ProjectMenu);
            app.SaveProjectasMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveProjectasMenuSelected, true);
            app.SaveProjectasMenu.Text = 'Save Project as';
            app.InfosMenu = uimenu(app.MIQASUIFigure);
            app.InfosMenu.Text = 'Infos';
            app.ShowSessioninImportModuleButton = uibutton(app.MIQASUIFigure, 'push');
            app.ShowSessioninImportModuleButton.ButtonPushedFcn = createCallbackFcn(app, @ShowSessioninImportModuleButtonPushed, true);
            app.ShowSessioninImportModuleButton.Text = 'Show Session in Import Module';
            app.AddSessionButton = uibutton(app.MIQASUIFigure, 'push');
            app.AddSessionButton.ButtonPushedFcn = createCallbackFcn(app, @AddSessionButtonPushed, true);
            app.AddSessionButton.Text = 'Add Session';
            app.SessionTable = uitable(app.MIQASUIFigure);
            app.SessionTable.ColumnName = '';
            app.SessionTable.RowName = {};
            app.SessionTable.ColumnSortable = [true true];
            app.SessionTable.ColumnEditable = [false true true];
            app.SessionTable.CellEditCallback = createCallbackFcn(app, @SessionTableCellEdit, true);
            app.SessionTable.Multiselect = 'off';
            app.DeleteSessionButton = uibutton(app.MIQASUIFigure, 'push');
            app.DeleteSessionButton.Text = 'Delete Session';
            app.ShowSessioninIntervalModuleButton = uibutton(app.MIQASUIFigure, 'push');
            app.ShowSessioninIntervalModuleButton.ButtonPushedFcn = createCallbackFcn(app, @ShowSessioninIntervalModuleButtonPushed, true);
            app.ShowSessioninIntervalModuleButton.Text = 'Show Session in Interval Module';
            app.ProjectTable = uitable(app.MIQASUIFigure);
            app.ProjectTable.ColumnName = '';
            app.ProjectTable.RowName = {};
            app.ProjectTable.ColumnEditable = [false true];
            app.ProjectTable.CellEditCallback = createCallbackFcn(app, @ProjectTableCellEdit, true);
            app.ProjectTable.SelectionChangedFcn = createCallbackFcn(app, @ProjectTableSelectionChanged, true);
            app.ProjectTable.Multiselect = 'off';
            app.AddProjectButton = uibutton(app.MIQASUIFigure, 'push');
            app.AddProjectButton.ButtonPushedFcn = createCallbackFcn(app, @AddProjectButtonPushed, true);
            app.AddProjectButton.Text = 'Add Project';
            app.DeleteProjectButton = uibutton(app.MIQASUIFigure, 'push');
            app.DeleteProjectButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteProjectButtonPushed, true);
            app.DeleteProjectButton.Text = 'Delete Project';
            app.ShowSessioninClassificationModuleButton = uibutton(app.MIQASUIFigure, 'push');
            app.ShowSessioninClassificationModuleButton.ButtonPushedFcn = createCallbackFcn(app, @ShowSessioninClassificationModuleButtonPushed, true);
            app.ShowSessioninClassificationModuleButton.Text = 'Show Session in Classification Module';
            app.ShowSessioninDriftModuleButton = uibutton(app.MIQASUIFigure, 'push');
            app.ShowSessioninDriftModuleButton.ButtonPushedFcn = createCallbackFcn(app, @ShowSessioninDriftModuleButtonPushed, true);
            app.ShowSessioninDriftModuleButton.Text = 'Show Session in Drift Module';
            app.ShowSessioninQuantificationModuleButton = uibutton(app.MIQASUIFigure, 'push');
            app.ShowSessioninQuantificationModuleButton.ButtonPushedFcn = createCallbackFcn(app, @ShowSessioninQuantificationModuleButtonPushed, true);
            app.ShowSessioninQuantificationModuleButton.Text = 'Show Session in Quantification Module';
            app.ShowSessioninResultsModuleButton = uibutton(app.MIQASUIFigure, 'push');
            app.ShowSessioninResultsModuleButton.Visible = 'off';
            app.ShowSessioninResultsModuleButton.Text = 'Show Session in Results Module';
            % Show the figure after all components are created
            app.MIQASUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = miqasa

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MIQASUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MIQASUIFigure)
        end
    end
end